classdef basicReportInfoGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        reportInfoGUI       matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        uipanel2            matlab.ui.container.Panel
        GridLayout4         matlab.ui.container.GridLayout
        reportDescText      matlab.ui.control.TextArea
        uipanel1            matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        spacecraftNameText  matlab.ui.control.EditField
        GridLayout2         matlab.ui.container.GridLayout
        resetFormButton     matlab.ui.control.Button
        saveButton          matlab.ui.control.Button
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)

        function saveAndClose(app, handles)
            app.output.output{1} = get(handles.spacecraftNameText, 'String');
            app.output.output{2} = char(get(handles.reportDescText, 'String'));
            close(handles.reportInfoGUI);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function basicReportInfoGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to basicReportInfoGUI (see VARARGIN)

            % Choose default command line output for basicReportInfoGUI
            handles.output = hObject;

            % Update handles structure
            guidata(hObject, handles);
            
            centerUIFigure(app.reportInfoGUI);
            
            
            app.output = varargin{1};

            % UIWAIT makes basicReportInfoGUI wait for user response (see UIRESUME)
            uiwait(handles.reportInfoGUI);
        end

        % Button pushed function: resetFormButton
        function resetFormButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to resetFormButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.spacecraftNameText, 'String', '');
            set(handles.reportDescText, 'String', '');
        end

        % Button pushed function: saveButton
        function saveButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
%             uiresume(handles.reportInfoGUI);
            app.saveAndClose(handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.reportInfoGUI = uifigure('Visible', 'off');
            colormap(app.reportInfoGUI, 'parula');
            app.reportInfoGUI.Name = 'Report Information';
            app.reportInfoGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.reportInfoGUI.Tag = 'reportInfoGUI';
            app.GridLayout = uigridlayout(app.reportInfoGUI);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveButton = uibutton(app.GridLayout2, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButton_Callback, true);
            app.saveButton.Tag = 'saveButton';
            app.saveButton.Text = 'Generate Report';
            app.resetFormButton = uibutton(app.GridLayout2, 'push');
            app.resetFormButton.ButtonPushedFcn = createCallbackFcn(app, @resetFormButton_Callback, true);
            app.resetFormButton.Tag = 'resetFormButton';
            app.resetFormButton.Text = 'Reset Form';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Title = 'Spacecraft Name';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.spacecraftNameText = uieditfield(app.GridLayout3, 'text');
            app.spacecraftNameText.Tag = 'spacecraftNameText';
            app.spacecraftNameText.Value = 'Generic Spacecraft';
            app.uipanel2 = uipanel(app.GridLayout);
            app.uipanel2.Title = 'Report Description';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4 = uigridlayout(app.uipanel2);
            app.reportDescText = uitextarea(app.GridLayout4);
            app.reportDescText.Tag = 'reportDescText';
            app.reportDescText.Value = {'Report Description'};
            % Show the figure after all components are created
            app.reportInfoGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basicReportInfoGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.reportInfoGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)basicReportInfoGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.reportInfoGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.reportInfoGUI)
        end
    end
end