classdef genericReport_App_DONOTUSE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        genericReportGUI      matlab.ui.Figure
        uitoolbar1            matlab.ui.container.Toolbar
        uipushtool1           matlab.ui.container.toolbar.PushTool
        uitoggletool1         matlab.ui.container.toolbar.ToggleTool
        userEnteredNotesText  matlab.ui.control.TextArea
        rightTextBoxLabel     matlab.ui.control.Label
        uipanel3              matlab.ui.container.Panel
        rightTextBox          matlab.ui.control.Label
        leftTextBoxLabel      matlab.ui.control.Label
        uipanel2              matlab.ui.container.Panel
        leftTextBox           matlab.ui.control.Label
        headerTextBox         matlab.ui.control.Label
        text6                 matlab.ui.control.Label
        uipanel1              matlab.ui.container.Panel
        text5                 matlab.ui.control.Label
        text4                 matlab.ui.control.Label
        text2                 matlab.ui.control.Label
        text1                 matlab.ui.control.Label
        rightAxis             matlab.ui.control.UIAxes
        leftAxis              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function genericReport_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to genericReport (see VARARGIN)

            % Choose default command line output for genericReport
            handles.output = hObject;

            % Update handles structure
            guidata(hObject, handles);
            
            centerUIFigure(app.genericReportGUI);
            applySelectedThemeToApp(app);
            
            app.genericReportGUI.Visible = 'off';

            axis(handles.leftAxis, 'off');
            axis(handles.rightAxis, 'off');

%             fillPage(gcf, 'margins', [0 0 0 0], 'papersize', [8.5 11]);

%             load('genericReportPrintSetup.mat'); %#ok<LOAD> 
%             genericReportPrintSetup.ResolutionMode = 'manual';
%             genericReportPrintSetup.DPI = 1000;
%             genericReportPrintSetup.PrintDriver = '-dwinc';
%             setprinttemplate(hObject, genericReportPrintSetup);

            manType = varargin{2};
            generatedOn = datestr(now(), 'yyyy-mm-dd HH:MM:SS.FFF');
            generatedBy = ['KSP TOT v', getKSPTOTVersionNumStr(), ' -- ', varargin{3}];
            
            output2 = AppDesignerGUIOutput({'',''});
            basicReportInfoGUI_App(output2);
            scName = output2.output{1};
            reportDesc = char(output2.output{2});
            
            headerTextBoxStr = {};
            headerTextBoxStr{end+1} = manType;
            headerTextBoxStr{end+1} = generatedOn;
            headerTextBoxStr{end+1} = generatedBy;
            headerTextBoxStr{end+1} = scName;
            headerTextBoxStr{end+1} = reportDesc;
            set(handles.headerTextBox, 'String', headerTextBoxStr);

            app.genericReportGUI.Visible = 'on';
            
            app.output = varargin{4};
            app.output.output{1} = handles.genericReportGUI;
            app.output.output{2} = handles;
        end

        % Callback function: uipushtool1
        function uipushtool1_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to uipushtool1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %
            [FileName,PathName,~] = uiputfile('*.png','Select Location To Save Report To');
            % export_fig([PathName,FileName],'-png', '-r200', '-a4');
            print([PathName,FileName], '-r600', '-dpng');
        end

        % Callback function: uitoggletool1
        function uitoggletool1_ClickedCallback(app, event)

            % Enable or disable rotation based on the
            % tool's current state.
            state = app.uitoggletool1.State;
            rotate3d(app.genericReportGUI, char(state));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.genericReportGUI = uifigure('Visible', 'off');
            colormap(app.genericReportGUI, 'parula');
            app.genericReportGUI.Name = 'KSP TOT Report';
            app.genericReportGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.genericReportGUI.Tag = 'genericReportGUI';
            app.uitoolbar1 = uitoolbar(app.genericReportGUI);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uipushtool1 = uipushtool(app.uitoolbar1);
            app.uipushtool1.Tag = 'uipushtool1';
            app.uipushtool1.Tooltip = 'Print Figure';
            app.uipushtool1.ClickedCallback = createCallbackFcn(app, @uipushtool1_ClickedCallback, true);
            app.uipushtool1.Icon = 'uipushtool1_image.png';
            app.uitoggletool1 = uitoggletool(app.uitoolbar1);
            app.uitoggletool1.Tag = 'uitoggletool1';
            app.uitoggletool1.Tooltip = 'Rotate 3D';
            app.uitoggletool1.ClickedCallback = createCallbackFcn(app, @uitoggletool1_ClickedCallback, true);
            app.uitoggletool1.Icon = 'uitoggletool1_image.png';
            app.uitoggletool1.Separator = 'on';
            app.leftAxis = uiaxes(app.genericReportGUI);
            app.leftAxis.Tag = 'leftAxis';
            app.rightAxis = uiaxes(app.genericReportGUI);
            app.rightAxis.Tag = 'rightAxis';
            app.uipanel1 = uipanel(app.genericReportGUI);
            app.uipanel1.Tag = 'uipanel1';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = {'_________________________________________'; 'Generated'};
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = {'_________________________________________'; 'Executed'};
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = {'_________________________________________'; 'Approved'};
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = {'_________________________________________'; 'Observed'};
            app.text6 = uilabel(app.genericReportGUI);
            app.text6.Tag = 'text6';
            app.text6.Text = {'MANEUVER TYPE:'; 'GENERATED ON:'; 'GENERATED BY:'; 'SPACECRAFT:'; 'DESCRIPTION:'};
            app.headerTextBox = uilabel(app.genericReportGUI);
            app.headerTextBox.Tag = 'headerTextBox';
            app.headerTextBox.Text = {'<maneuverType>'; '<generatedOn>'; '<GeneratedBy>'; '<spacecraft>'; '<description>'};
            app.uipanel2 = uipanel(app.genericReportGUI);
            app.uipanel2.Tag = 'uipanel2';
            app.leftTextBox = uilabel(app.uipanel2);
            app.leftTextBox.Tag = 'leftTextBox';
            app.leftTextBox.Text = '';
            app.leftTextBoxLabel = uilabel(app.genericReportGUI);
            app.leftTextBoxLabel.Tag = 'leftTextBoxLabel';
            app.leftTextBoxLabel.Text = 'Maneuver 1 Execution Details';
            app.uipanel3 = uipanel(app.genericReportGUI);
            app.uipanel3.Tag = 'uipanel3';
            app.rightTextBox = uilabel(app.uipanel3);
            app.rightTextBox.Tag = 'rightTextBox';
            app.rightTextBox.Text = '';
            app.rightTextBoxLabel = uilabel(app.genericReportGUI);
            app.rightTextBoxLabel.Tag = 'rightTextBoxLabel';
            app.rightTextBoxLabel.Text = 'Maneuver 2 Execution Details';
            app.userEnteredNotesText = uitextarea(app.genericReportGUI);
            app.userEnteredNotesText.Tag = 'userEnteredNotesText';
            app.userEnteredNotesText.Value = {'Enter analyst or operator notes here...'};
            % Show the figure after all components are created
            app.genericReportGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = genericReport_App_DONOTUSE(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.genericReportGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)genericReport_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.genericReportGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.genericReportGUI)
        end
    end
end