classdef editTwoBodyRotatingFrameGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        editTwoBodyRotatingFrameGUI  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        uipanel1                     matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        frameOriginCombo             matlab.ui.control.DropDown
        text4                        matlab.ui.control.Label
        secondaryBodyCombo           matlab.ui.control.DropDown
        text2_1                      matlab.ui.control.Label
        primaryBodyCombo             matlab.ui.control.DropDown
        text1                        matlab.ui.control.Label
        GridLayout2                  matlab.ui.container.GridLayout
        cancelButton                 matlab.ui.control.Button
        saveAndCloseButton           matlab.ui.control.Button
        text2                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput
    end
    
    methods (Access = private)
        function handles = populateGUI(app, frame, handles)
                celBodyData = frame.celBodyData;
                [bodiesStr, sortedBodyInfo] = ma_getSortedBodyNames(celBodyData);
            
                for(i=1:length(sortedBodyInfo))
                    sortedBodyInfoArr(i) = sortedBodyInfo{i}; %#ok<AGROW>
                end
            
                handles.primaryBodyCombo.String = bodiesStr;
                ind = find(frame.primaryBodyInfo == sortedBodyInfoArr,1,'first');
                handles.primaryBodyCombo.Value = ind;
            
                handles.secondaryBodyCombo.String = bodiesStr;
                ind = find(frame.secondaryBodyInfo == sortedBodyInfoArr,1,'first');
                handles.secondaryBodyCombo.Value = ind;
            
                handles.frameOriginCombo.String = TwoBodyRotatingFrameOriginEnum.getListBoxStr();
                handles.frameOriginCombo.Value = TwoBodyRotatingFrameOriginEnum.getIndForName(frame.originPt.name);
        end
        
        function errMsg = validateInputs(app, handles)
                errMsg = {};
            
                frame = getappdata(handles.editTwoBodyRotatingFrameGUI,'frame');
            
                celBodyData = frame.celBodyData;
                [~, sortedBodyInfo] = ma_getSortedBodyNames(celBodyData);
            
                primaryBodyInfo = sortedBodyInfo{handles.primaryBodyCombo.Value};
                secondaryBodyInfo = sortedBodyInfo{handles.secondaryBodyCombo.Value};
            
                if(primaryBodyInfo == secondaryBodyInfo)
                    errMsg{end+1} = 'The primary body must be different than the secondary body.';
                end
        end
        
        
        function saveAndClose(app, handles)
            frame = getappdata(handles.editTwoBodyRotatingFrameGUI,'frame');
            
            celBodyData = frame.celBodyData;
            [~, sortedBodyInfo] = ma_getSortedBodyNames(celBodyData);
            
            frame.primaryBodyInfo = sortedBodyInfo{handles.primaryBodyCombo.Value};
            frame.secondaryBodyInfo = sortedBodyInfo{handles.secondaryBodyCombo.Value};
            
            contents = cellstr(get(handles.frameOriginCombo,'String'));
            originPtName = contents{get(handles.frameOriginCombo,'Value')};
            frame.originPt = TwoBodyRotatingFrameOriginEnum.getEnumForListboxStr(originPtName);
            
            app.output.output = {true};
            close(handles.editTwoBodyRotatingFrameGUI);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function editTwoBodyRotatingFrameGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
                % This function has no output args, see OutputFcn.
                % hObject    handle to figure
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
                % varargin   command line arguments to editTwoBodyRotatingFrameGUI (see VARARGIN)
            
                % Choose default command line output for editTwoBodyRotatingFrameGUI
                handles.output = hObject;
            
                centerUIFigure(app.editTwoBodyRotatingFrameGUI);
                applySelectedThemeToApp(app);
                
                frame = varargin{1};
                setappdata(hObject,'frame',frame);
                
                app.output = varargin{2};
            
                handles = populateGUI(app, frame, handles);
            
                % Update handles structure
                guidata(hObject, handles);
            
                % UIWAIT makes editTwoBodyRotatingFrameGUI wait for user response (see UIRESUME)
                uiwait(handles.editTwoBodyRotatingFrameGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % hObject    handle to cancelButton (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
                close(handles.editTwoBodyRotatingFrameGUI);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % hObject    handle to saveAndCloseButton (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
                errMsg = validateInputs(app, handles);
            
                if(isempty(errMsg))
                    app.saveAndClose(handles);
                else
%                     msgbox(errMsg,'Invalid Two-Body Rotating Frame Inputs','error');
                    uialert(app.editTwoBodyRotatingFrameGUI, errMsg, 'Invalid Two-Body Rotating Frame Inputs', "Icon","error");
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.editTwoBodyRotatingFrameGUI = uifigure('Visible', 'off');
            app.editTwoBodyRotatingFrameGUI.Name = 'Edit Two-Body Rotating Frame';
            app.editTwoBodyRotatingFrameGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.editTwoBodyRotatingFrameGUI.Tag = 'editTwoBodyRotatingFrameGUI';
            app.GridLayout = uigridlayout(app.editTwoBodyRotatingFrameGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Two-Body Rotating Frame';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.text1 = uilabel(app.GridLayout3);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Primary Body';
            app.primaryBodyCombo = uidropdown(app.GridLayout3);
            app.primaryBodyCombo.Items = {'Pop-up Menu'};
            app.primaryBodyCombo.Tag = 'primaryBodyCombo';
            app.primaryBodyCombo.Tooltip = 'The primary body should be the more massive of the two bodies.';
            app.primaryBodyCombo.Value = 'Pop-up Menu';
            app.text2_1 = uilabel(app.GridLayout3);
            app.text2_1.Tag = 'text2_1';
            app.text2_1.Text = 'Secondary Body';
            app.secondaryBodyCombo = uidropdown(app.GridLayout3);
            app.secondaryBodyCombo.Items = {'Pop-up Menu'};
            app.secondaryBodyCombo.Tag = 'secondaryBodyCombo';
            app.secondaryBodyCombo.Tooltip = 'The secondary body should be the smaller of the two bodies.  While not required, the secondary body typically directly orbits the primary.';
            app.secondaryBodyCombo.Value = 'Pop-up Menu';
            app.text4 = uilabel(app.GridLayout3);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Frame Origin';
            app.frameOriginCombo = uidropdown(app.GridLayout3);
            app.frameOriginCombo.Items = {'Pop-up Menu'};
            app.frameOriginCombo.Tag = 'frameOriginCombo';
            app.frameOriginCombo.Tooltip = 'The origin point of the reference frame.';
            app.frameOriginCombo.Value = 'Pop-up Menu';
            % Show the figure after all components are created
            app.editTwoBodyRotatingFrameGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = editTwoBodyRotatingFrameGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.editTwoBodyRotatingFrameGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)editTwoBodyRotatingFrameGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.editTwoBodyRotatingFrameGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.editTwoBodyRotatingFrameGUI)
        end
    end
end