classdef lvd_AddDeltaVActionGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_AddDeltaVActionGUI  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        uipanel1                matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        paramTypeCombo          matlab.ui.control.DropDown
        paramTypeLabel          matlab.ui.control.Label
        userFrameCombo          matlab.ui.control.DropDown
        userFrameLabel          matlab.ui.control.Label
        GridLayout6             matlab.ui.container.GridLayout
        ubText3                 matlab.ui.control.EditField
        lbText3                 matlab.ui.control.EditField
        optCheckbox3            matlab.ui.control.CheckBox
        text17                  matlab.ui.control.Label
        dvZCompText             matlab.ui.control.EditField
        GridLayout5             matlab.ui.container.GridLayout
        ubText2                 matlab.ui.control.EditField
        lbText2                 matlab.ui.control.EditField
        optCheckbox2            matlab.ui.control.CheckBox
        text15                  matlab.ui.control.Label
        dvYCompText             matlab.ui.control.EditField
        GridLayout4             matlab.ui.container.GridLayout
        ubText1                 matlab.ui.control.EditField
        lbText1                 matlab.ui.control.EditField
        optCheckbox1            matlab.ui.control.CheckBox
        text6                   matlab.ui.control.Label
        dvXCompText             matlab.ui.control.EditField
        dvFrameCombo            matlab.ui.control.DropDown
        subtractMassCheckbox    matlab.ui.control.CheckBox
        zCompLabel              matlab.ui.control.Label
        yCompLabel              matlab.ui.control.Label
        xCompLabel              matlab.ui.control.Label
        text13                  matlab.ui.control.Label
        GridLayout2             matlab.ui.container.GridLayout
        cancelButton            matlab.ui.control.Button
        saveAndCloseButton      matlab.ui.control.Button
        text2                   matlab.ui.control.Label
    end

    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, action, lvdData)
                handles.dvFrameCombo.String = DeltaVFrameEnum.getListBoxStr();
                handles.dvFrameCombo.Value = DeltaVFrameEnum.getIndForName(action.frame.nameStr);

                [paramListBoxStr, ~] = DeltaVParamTypeEnum.getListBoxStr();
                app.paramTypeCombo.Items = paramListBoxStr;
                app.paramTypeCombo.Value = action.paramType.nameStr;

                app.populateUserFrameCombo(action, lvdData);

                dispVals = app.getDisplayValuesFromStored(action.deltaVVect(:), action.paramType);
                handles.dvXCompText.String = fullAccNum2Str(dispVals(1));
                handles.dvYCompText.String = fullAccNum2Str(dispVals(2));
                handles.dvZCompText.String = fullAccNum2Str(dispVals(3));
            
                optVar = action.optVar;
            	if(isempty(optVar))
                    optVar = AddDeltaVActionVariable(action);
            	end
            
                tf = optVar.getUseTfForVariable();
                handles.optCheckbox1.Value = double(tf(1));
                handles.optCheckbox2.Value = double(tf(2));
                handles.optCheckbox3.Value = double(tf(3));
            
                [lb, ub] = optVar.getAllBndsForVariable();
                lb = app.getDisplayValuesFromStored(lb(:), action.paramType);
                ub = app.getDisplayValuesFromStored(ub(:), action.paramType);
            
                handles.lbText1.String = fullAccNum2Str(lb(1));
                handles.ubText1.String = fullAccNum2Str(ub(1));
            
                handles.lbText2.String = fullAccNum2Str(lb(2));
                handles.ubText2.String = fullAccNum2Str(ub(2));
            
                handles.lbText3.String = fullAccNum2Str(lb(3));
                handles.ubText3.String = fullAccNum2Str(ub(3));
            
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                
                dvFrameCombo_Callback(app, evt);
                optCheckbox1_Callback(app, evt);
                optCheckbox2_Callback(app, evt);
                optCheckbox3_Callback(app, evt);
            
                handles.subtractMassCheckbox.Value = double(action.useDeltaMass);
        end
        
        function errMsg = validateInputs(app, handles)
                errMsg = {};
            
                contents = cellstr(get(handles.dvFrameCombo,'String'));
                frameEnum = DeltaVFrameEnum.getEnumForListboxStr(contents{get(handles.dvFrameCombo,'Value')});
                paramType = DeltaVParamTypeEnum.getEnumForListboxStr(app.paramTypeCombo.Value);

                if(paramType == DeltaVParamTypeEnum.Polar)
                    compNames = AddDeltaVAction.getPolarComponentNames();
                    comp1LwrLimit = 0; %a magnitude cannot be negative
                else
                    compNames = frameEnum.compNames;
                    comp1LwrLimit = -Inf;
                end

                if(frameEnum == DeltaVFrameEnum.UserFrame && isempty(app.getSelectedUserFrame()))
                    errMsg{end+1} = 'A user-defined frame must be selected when the delta-v frame is "User-Defined Frame".  Define a geometric reference frame first (Geometry -> Edit Reference Frames).';
                end

                %Component 1
                val = str2double(get(handles.dvXCompText,'String'));
                enteredStr = get(handles.dvXCompText,'String');
                numberName = sprintf('Delta-V: %s', compNames{1});
                lb = comp1LwrLimit;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                lwrBnd = str2double(get(handles.lbText1,'String'));
                enteredStr = get(handles.lbText1,'String');
                numberName = sprintf('Delta-V: %s (Lower Bound)', compNames{1});
                lb = -Inf;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(lwrBnd, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                uprBnd = str2double(get(handles.ubText1,'String'));
                enteredStr = get(handles.ubText1,'String');
                numberName = sprintf('Delta-V: %s (Upper Bound)', compNames{1});
                lb = -Inf;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(uprBnd, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                if(isempty(errMsg))
                    uprBnd = str2double(get(handles.ubText1,'String'));
                    enteredStr = get(handles.ubText1,'String');
                    numberName = sprintf('Delta-V: %s (Upper Bound)', compNames{1});
                    lb = lwrBnd;
                    ub = Inf;
                    isInt = false;
                    errMsg = validateNumber(uprBnd, numberName, lb, ub, isInt, errMsg, enteredStr);
                end
            
                %Component 2
                val = str2double(get(handles.dvYCompText,'String'));
                enteredStr = get(handles.dvYCompText,'String');
                numberName = sprintf('Delta-V: %s', compNames{2});
                lb = -Inf;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                lwrBnd = str2double(get(handles.lbText2,'String'));
                enteredStr = get(handles.lbText2,'String');
                numberName = sprintf('Delta-V: %s (Lower Bound)', compNames{2});
                lb = -Inf;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(lwrBnd, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                uprBnd = str2double(get(handles.ubText2,'String'));
                enteredStr = get(handles.ubText2,'String');
                numberName = sprintf('Delta-V: %s (Upper Bound)', compNames{2});
                lb = -Inf;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(uprBnd, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                if(isempty(errMsg))
                    uprBnd = str2double(get(handles.ubText2,'String'));
                    enteredStr = get(handles.ubText2,'String');
                    numberName = sprintf('Delta-V: %s (Upper Bound)', compNames{2});
                    lb = lwrBnd;
                    ub = Inf;
                    isInt = false;
                    errMsg = validateNumber(uprBnd, numberName, lb, ub, isInt, errMsg, enteredStr);
                end
            
                %Component 3
                val = str2double(get(handles.dvZCompText,'String'));
                enteredStr = get(handles.dvZCompText,'String');
                numberName = sprintf('Delta-V: %s', compNames{3});
                lb = -Inf;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                lwrBnd = str2double(get(handles.lbText3,'String'));
                enteredStr = get(handles.lbText3,'String');
                numberName = sprintf('Delta-V: %s (Lower Bound)', compNames{3});
                lb = -Inf;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(lwrBnd, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                uprBnd = str2double(get(handles.ubText3,'String'));
                enteredStr = get(handles.ubText3,'String');
                numberName = sprintf('Delta-V: %s (Upper Bound)', compNames{3});
                lb = -Inf;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(uprBnd, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                if(isempty(errMsg))
                    uprBnd = str2double(get(handles.ubText3,'String'));
                    enteredStr = get(handles.ubText3,'String');
                    numberName = sprintf('Delta-V: %s (Upper Bound)', compNames{3});
                    lb = lwrBnd;
                    ub = Inf;
                    isInt = false;
                    errMsg = validateNumber(uprBnd, numberName, lb, ub, isInt, errMsg, enteredStr);
                end
        end
        
        function onParamTypeComboChanged(app, ~, evt)
            %Convert the three displayed numbers to the newly selected
            %parameterization so the delta-v itself is unchanged.  Bounds
            %are left for the user to revisit.
            [~, ~, handles] = convertToGUIDECallbackArguments(app);

            newParamType = DeltaVParamTypeEnum.getEnumForListboxStr(app.paramTypeCombo.Value);

            oldParamType = DeltaVParamTypeEnum.empty(1,0);
            if(isa(evt, 'matlab.ui.eventdata.ValueChangedData') && (ischar(evt.PreviousValue) || isstring(evt.PreviousValue)))
                oldParamType = DeltaVParamTypeEnum.getEnumForListboxStr(char(evt.PreviousValue));
            end

            if(not(isempty(oldParamType)) && oldParamType ~= newParamType)
                dispVals = [str2double(handles.dvXCompText.String);
                            str2double(handles.dvYCompText.String);
                            str2double(handles.dvZCompText.String)];

                if(all(isfinite(dispVals)))
                    storedVals = app.getStoredValuesFromDisplay(dispVals, oldParamType);

                    if(newParamType == DeltaVParamTypeEnum.Polar)
                        storedVals = AddDeltaVAction.cartesianToPolar(storedVals);
                    else
                        storedVals = AddDeltaVAction.polarToCartesian(storedVals);
                    end

                    newDispVals = app.getDisplayValuesFromStored(storedVals, newParamType);
                    handles.dvXCompText.String = fullAccNum2Str(newDispVals(1));
                    handles.dvYCompText.String = fullAccNum2Str(newDispVals(2));
                    handles.dvZCompText.String = fullAccNum2Str(newDispVals(3));
                end
            end

            app.updateComponentLabelsAndUnits(handles);
        end

        function populateUserFrameCombo(app, action, lvdData)
            %Lists the mission's geometric reference frames; the delta-v can
            %be expressed in any of them when the frame is "User-Defined".
            [listBoxStr, refFrames] = lvdData.geometry.refFrames.getListboxStr();

            if(isempty(refFrames))
                app.userFrameCombo.Items = {'<No Geometric Frames Defined>'};
                app.userFrameCombo.ItemsData = {[]}; %Value defaults to the lone (empty) entry
            else
                app.userFrameCombo.Items = listBoxStr;
                app.userFrameCombo.ItemsData = num2cell(refFrames);

                selInd = 1;
                if(not(isempty(action.userFrame)) && isa(action.userFrame, 'UserDefinedGeometricFrame'))
                    ind = find(refFrames == action.userFrame.geometricFrame, 1, 'first');
                    if(not(isempty(ind)))
                        selInd = ind;
                    end
                end

                app.userFrameCombo.Value = app.userFrameCombo.ItemsData{selInd};
            end
        end

        function userFrame = getSelectedUserFrame(app)
            %The selected geometric frame wrapped as an AbstractReferenceFrame,
            %or empty when no geometric frames exist.
            userFrame = AbstractReferenceFrame.empty(1,0);

            geomFrame = app.userFrameCombo.Value;
            if(not(isempty(geomFrame)) && isa(geomFrame, 'AbstractGeometricRefFrame'))
                lvdData = getappdata(app.lvd_AddDeltaVActionGUI, 'lvdData');
                userFrame = UserDefinedGeometricFrame(geomFrame, lvdData);
            end
        end

        function dispVals = getDisplayValuesFromStored(~, storedVals, paramType)
            %Stored km/s (and rad) -> displayed m/s (and deg).
            storedVals = storedVals(:);
            if(paramType == DeltaVParamTypeEnum.Polar)
                dispVals = [storedVals(1)*1000; rad2deg(storedVals(2)); rad2deg(storedVals(3))];
            else
                dispVals = storedVals*1000;
            end
        end

        function storedVals = getStoredValuesFromDisplay(~, dispVals, paramType)
            %Displayed m/s (and deg) -> stored km/s (and rad).
            dispVals = dispVals(:);
            if(paramType == DeltaVParamTypeEnum.Polar)
                storedVals = [dispVals(1)/1000; deg2rad(dispVals(2)); deg2rad(dispVals(3))];
            else
                storedVals = dispVals/1000;
            end
        end

        function updateComponentLabelsAndUnits(app, handles)
            contents = cellstr(get(handles.dvFrameCombo,'String'));
            frameEnum = DeltaVFrameEnum.getEnumForListboxStr(contents{get(handles.dvFrameCombo,'Value')});
            paramType = DeltaVParamTypeEnum.getEnumForListboxStr(app.paramTypeCombo.Value);

            if(paramType == DeltaVParamTypeEnum.Polar)
                compNames = AddDeltaVAction.getPolarComponentNames();
                unitStrs = {'m/s', 'deg', 'deg'};
            else
                compNames = frameEnum.compNames;
                unitStrs = {'m/s', 'm/s', 'm/s'};
            end

            handles.xCompLabel.String = compNames{1};
            handles.yCompLabel.String = compNames{2};
            handles.zCompLabel.String = compNames{3};

            app.text6.Text = unitStrs{1};
            app.text15.Text = unitStrs{2};
            app.text17.Text = unitStrs{3};

            if(frameEnum == DeltaVFrameEnum.UserFrame)
                app.userFrameCombo.Enable = 'on';
            else
                app.userFrameCombo.Enable = 'off';
            end
        end

        function saveAndClose(app, handles)
            action = getappdata(handles.lvd_AddDeltaVActionGUI, 'action');
            lvdData = getappdata(handles.lvd_AddDeltaVActionGUI, 'lvdData');
            
            paramType = DeltaVParamTypeEnum.getEnumForListboxStr(app.paramTypeCombo.Value);

            dvVect = [str2double(handles.dvXCompText.String);
                      str2double(handles.dvYCompText.String);
                      str2double(handles.dvZCompText.String)];
            dvVect = app.getStoredValuesFromDisplay(dvVect, paramType); %store as km/s (and rad for polar angles)

            action.paramType = paramType;
            action.deltaVVect = dvVect;

            contents = cellstr(get(handles.dvFrameCombo,'String'));
            frameEnum = DeltaVFrameEnum.getEnumForListboxStr(contents{get(handles.dvFrameCombo,'Value')});
            action.frame = frameEnum;

            if(frameEnum == DeltaVFrameEnum.UserFrame)
                action.userFrame = app.getSelectedUserFrame();
            end
            
            action.useDeltaMass = logical(handles.subtractMassCheckbox.Value);
            
            optVar = action.optVar;
            if(not(isempty(optVar))) %need to remove existing var if it exists
                lvdData.optimizer.vars.removeVariable(optVar);
            end
                    
            %Set Opt T/F
            useTf = [get(handles.optCheckbox1,'Value'), ...
                     get(handles.optCheckbox2,'Value'), ...
                     get(handles.optCheckbox3,'Value')];
            
    %         if(isempty(optVar))
    %             optVar = AddDeltaVActionVariable(action);
    %         end
            optVar = action.optVar;
            if(not(isempty(optVar)))
                lvdData.optimizer.vars.removeVariable(optVar);
            end
            optVar = AddDeltaVActionVariable(action);
            
            optVar.setUseTfForVariable(useTf);
            
            %Bnds
            lb = [str2double(get(handles.lbText1,'String')), ...
                  str2double(get(handles.lbText2,'String')), ...
                  str2double(get(handles.lbText3,'String'))];
            lb = app.getStoredValuesFromDisplay(lb(:), paramType)'; %store as km/s (and rad for polar angles)

            ub = [str2double(get(handles.ubText1,'String')), ...
                  str2double(get(handles.ubText2,'String')), ...
                  str2double(get(handles.ubText3,'String'))];
            ub = app.getStoredValuesFromDisplay(ub(:), paramType)'; %store as km/s (and rad for polar angles)
            
            optVar.setUseTfForVariable(true(size(lb))); %need this to get the full lb/set in there
            optVar.setBndsForVariable(lb, ub);
            optVar.setUseTfForVariable(useTf);
                   
            lvdData.optimizer.vars.addVariable(optVar);
            
            app.output.output = {true};
            close(handles.lvd_AddDeltaVActionGUI);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_AddDeltaVActionGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
                % This function has no output args, see OutputFcn.
                % hObject    handle to figure
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
                % varargin   command line arguments to lvd_AddDeltaVActionGUI (see VARARGIN)
            
                % Choose default command line output for lvd_AddDeltaVActionGUI
                handles.output = hObject;
            
                centerUIFigure(hObject);
                applySelectedThemeToApp(app);
            
                action = varargin{1};
                setappdata(hObject, 'action', action);
            
                lvdData = varargin{2};
                setappdata(hObject, 'lvdData', lvdData);
                
                app.output = varargin{3};
            
            	populateGUI(app, handles, action, lvdData);
            
                % Update handles structure
                guidata(hObject, handles);
            
                % UIWAIT makes lvd_AddDeltaVActionGUI wait for user response (see UIRESUME)
                uiwait(handles.lvd_AddDeltaVActionGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                close(handles.lvd_AddDeltaVActionGUI);
        end

        % Value changed function: dvFrameCombo
        function dvFrameCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.dvFrameCombo;
            
            % hObject    handle to dvFrameCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns dvFrameCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from dvFrameCombo
                app.updateComponentLabelsAndUnits(handles);
        end

        % Value changed function: dvXCompText
        function dvXCompText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to dvXCompText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of dvXCompText as text
            %        str2double(get(hObject,'String')) returns contents of dvXCompText as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
        end

        % Value changed function: dvYCompText
        function dvYCompText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to dvYCompText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of dvYCompText as text
            %        str2double(get(hObject,'String')) returns contents of dvYCompText as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
        end

        % Value changed function: dvZCompText
        function dvZCompText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to dvZCompText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of dvZCompText as text
            %        str2double(get(hObject,'String')) returns contents of dvZCompText as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
        end

        % Value changed function: lbText1
        function lbText1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to lbText1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of lbText1 as text
            %        str2double(get(hObject,'String')) returns contents of lbText1 as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
        end

        % Value changed function: lbText2
        function lbText2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to lbText2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of lbText2 as text
            %        str2double(get(hObject,'String')) returns contents of lbText2 as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
        end

        % Value changed function: lbText3
        function lbText3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to lbText3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of lbText3 as text
            %        str2double(get(hObject,'String')) returns contents of lbText3 as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
        end

        % Window key press function: lvd_AddDeltaVActionGUI
        function lvd_AddDeltaVActionGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to lvd_AddDeltaVActionGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
                switch(eventdata.Key)
                    case 'return'
                        saveAndCloseButton_Callback(app, evt);
                    case 'enter'
                        saveAndCloseButton_Callback(app, evt);
                    case 'escape'
                        close(handles.lvd_AddDeltaVActionGUI);
                end
        end

        % Value changed function: optCheckbox1
        function optCheckbox1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.optCheckbox1;
            
            % hObject    handle to optCheckbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of optCheckbox1
                if(get(hObject,'Value'))
                    handles.lbText1.Enable = 'on';
                    handles.ubText1.Enable = 'on';
                else
                    handles.lbText1.Enable = 'off';
                    handles.ubText1.Enable = 'off';
                end
        end

        % Value changed function: optCheckbox2
        function optCheckbox2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.optCheckbox2;
            
            % hObject    handle to optCheckbox2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of optCheckbox2
                if(get(hObject,'Value'))
                    handles.lbText2.Enable = 'on';
                    handles.ubText2.Enable = 'on';
                else
                    handles.lbText2.Enable = 'off';
                    handles.ubText2.Enable = 'off';
                end
        end

        % Value changed function: optCheckbox3
        function optCheckbox3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.optCheckbox3;
            
            % hObject    handle to optCheckbox3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of optCheckbox3
                if(get(hObject,'Value'))
                    handles.lbText3.Enable = 'on';
                    handles.ubText3.Enable = 'on';
                else
                    handles.lbText3.Enable = 'off';
                    handles.ubText3.Enable = 'off';
                end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                errMsg = validateInputs(app, handles);
            
                if(isempty(errMsg))
                    app.saveAndClose(handles);
                else
                    uialert(app.lvd_AddDeltaVActionGUI, errMsg, 'Invalid Delta-V Inputs', "Icon","error");
                end
        end

        % Value changed function: ubText1
        function ubText1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ubText1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ubText1 as text
            %        str2double(get(hObject,'String')) returns contents of ubText1 as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
        end

        % Value changed function: ubText2
        function ubText2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ubText2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ubText2 as text
            %        str2double(get(hObject,'String')) returns contents of ubText2 as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
        end

        % Value changed function: ubText3
        function ubText3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ubText3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ubText3 as text
            %        str2double(get(hObject,'String')) returns contents of ubText3 as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
        end

        % Value changed function: paramTypeCombo
        function paramTypeComboValueChanged(app, event)
            app.onParamTypeComboChanged(app.paramTypeCombo, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_AddDeltaVActionGUI = uifigure('Visible', 'off');
            app.lvd_AddDeltaVActionGUI.Name = 'Edit Action';
            app.lvd_AddDeltaVActionGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_AddDeltaVActionGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_AddDeltaVActionGUI_WindowKeyPressFcn, true);
            app.lvd_AddDeltaVActionGUI.Tag = 'lvd_AddDeltaVActionGUI';
            app.GridLayout = uigridlayout(app.lvd_AddDeltaVActionGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Action';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.text13 = uilabel(app.GridLayout3);
            app.text13.Tag = 'text13';
            app.text13.Tooltip = 'The stage this tank is attached to.';
            app.text13.Text = 'Delta-V Frame';
            app.xCompLabel = uilabel(app.GridLayout3);
            app.xCompLabel.Tag = 'xCompLabel';
            app.xCompLabel.Text = 'X';
            app.yCompLabel = uilabel(app.GridLayout3);
            app.yCompLabel.Tag = 'yCompLabel';
            app.yCompLabel.Text = 'Y';
            app.zCompLabel = uilabel(app.GridLayout3);
            app.zCompLabel.Tag = 'zCompLabel';
            app.zCompLabel.Text = 'Z';
            app.subtractMassCheckbox = uicheckbox(app.GridLayout3);
            app.subtractMassCheckbox.Tag = 'subtractMassCheckbox';
            app.subtractMassCheckbox.Tooltip = 'If this checkbox is checked, then the propagator will also subtract an amount of propellant mass from the relevant fuel tanks in order to achieve the specified delta-v using the currently active engines.';
            app.subtractMassCheckbox.Text = 'Calculate and Apply Delta Propellant Mass';
            app.subtractMassCheckbox.Value = true;
            app.dvFrameCombo = uidropdown(app.GridLayout3);
            app.dvFrameCombo.Items = {'Pop-up Menu'};
            app.dvFrameCombo.ValueChangedFcn = createCallbackFcn(app, @dvFrameCombo_Callback, true);
            app.dvFrameCombo.Tag = 'dvFrameCombo';
            app.dvFrameCombo.Value = 'Pop-up Menu';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.dvXCompText = uieditfield(app.GridLayout4, 'text');
            app.dvXCompText.ValueChangedFcn = createCallbackFcn(app, @dvXCompText_Callback, true);
            app.dvXCompText.Tag = 'dvXCompText';
            app.dvXCompText.Value = '1';
            app.text6 = uilabel(app.GridLayout4);
            app.text6.Tag = 'text6';
            app.text6.Text = 'm/s';
            app.optCheckbox1 = uicheckbox(app.GridLayout4);
            app.optCheckbox1.ValueChangedFcn = createCallbackFcn(app, @optCheckbox1_Callback, true);
            app.optCheckbox1.Tag = 'optCheckbox1';
            app.optCheckbox1.Tooltip = 'Select to optimize this quantity.';
            app.optCheckbox1.Text = 'Opt?';
            app.optCheckbox1.Value = true;
            app.lbText1 = uieditfield(app.GridLayout4, 'text');
            app.lbText1.ValueChangedFcn = createCallbackFcn(app, @lbText1_Callback, true);
            app.lbText1.Tag = 'lbText1';
            app.lbText1.Tooltip = 'Parameter lower bound.';
            app.lbText1.Value = '0.0';
            app.ubText1 = uieditfield(app.GridLayout4, 'text');
            app.ubText1.ValueChangedFcn = createCallbackFcn(app, @ubText1_Callback, true);
            app.ubText1.Tag = 'ubText1';
            app.ubText1.Tooltip = 'Parameter upper bound.';
            app.ubText1.Value = '0.0';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.dvYCompText = uieditfield(app.GridLayout5, 'text');
            app.dvYCompText.ValueChangedFcn = createCallbackFcn(app, @dvYCompText_Callback, true);
            app.dvYCompText.Tag = 'dvYCompText';
            app.dvYCompText.Value = '1';
            app.text15 = uilabel(app.GridLayout5);
            app.text15.Tag = 'text15';
            app.text15.Text = 'm/s';
            app.optCheckbox2 = uicheckbox(app.GridLayout5);
            app.optCheckbox2.ValueChangedFcn = createCallbackFcn(app, @optCheckbox2_Callback, true);
            app.optCheckbox2.Tag = 'optCheckbox2';
            app.optCheckbox2.Tooltip = 'Select to optimize this quantity.';
            app.optCheckbox2.Text = 'Opt?';
            app.optCheckbox2.Value = true;
            app.lbText2 = uieditfield(app.GridLayout5, 'text');
            app.lbText2.ValueChangedFcn = createCallbackFcn(app, @lbText2_Callback, true);
            app.lbText2.Tag = 'lbText2';
            app.lbText2.Tooltip = 'Parameter lower bound.';
            app.lbText2.Value = '0.0';
            app.ubText2 = uieditfield(app.GridLayout5, 'text');
            app.ubText2.ValueChangedFcn = createCallbackFcn(app, @ubText2_Callback, true);
            app.ubText2.Tag = 'ubText2';
            app.ubText2.Tooltip = 'Parameter upper bound.';
            app.ubText2.Value = '0.0';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.dvZCompText = uieditfield(app.GridLayout6, 'text');
            app.dvZCompText.ValueChangedFcn = createCallbackFcn(app, @dvZCompText_Callback, true);
            app.dvZCompText.Tag = 'dvZCompText';
            app.dvZCompText.Value = '1';
            app.text17 = uilabel(app.GridLayout6);
            app.text17.Tag = 'text17';
            app.text17.Text = 'm/s';
            app.optCheckbox3 = uicheckbox(app.GridLayout6);
            app.optCheckbox3.ValueChangedFcn = createCallbackFcn(app, @optCheckbox3_Callback, true);
            app.optCheckbox3.Tag = 'optCheckbox3';
            app.optCheckbox3.Tooltip = 'Select to optimize this quantity.';
            app.optCheckbox3.Text = 'Opt?';
            app.optCheckbox3.Value = true;
            app.lbText3 = uieditfield(app.GridLayout6, 'text');
            app.lbText3.ValueChangedFcn = createCallbackFcn(app, @lbText3_Callback, true);
            app.lbText3.Tag = 'lbText3';
            app.lbText3.Tooltip = 'Parameter lower bound.';
            app.lbText3.Value = '0.0';
            app.ubText3 = uieditfield(app.GridLayout6, 'text');
            app.ubText3.ValueChangedFcn = createCallbackFcn(app, @ubText3_Callback, true);
            app.ubText3.Tag = 'ubText3';
            app.ubText3.Tooltip = 'Parameter upper bound.';
            app.ubText3.Value = '0.0';
            app.userFrameLabel = uilabel(app.GridLayout3);
            app.userFrameLabel.Tooltip = {'The geometric reference frame the delta-v components are expressed in when the delta-v frame is "User-Defined Frame".'};
            app.userFrameLabel.Text = 'User Frame';
            app.userFrameCombo = uidropdown(app.GridLayout3);
            app.userFrameCombo.Items = {'<No Geometric Frames Defined>'};
            app.userFrameCombo.Tooltip = {'Only used when the delta-v frame is "User-Defined Frame".  Geometric reference frames are created under Geometry -> Edit Reference Frames.'};
            app.userFrameCombo.Value = '<No Geometric Frames Defined>';
            app.paramTypeLabel = uilabel(app.GridLayout3);
            app.paramTypeLabel.Tooltip = {'Cartesian: three frame components.  Magnitude / Angles: a delta-v magnitude, an in-plane angle measured from the first frame axis toward the second, and an out-of-plane angle toward the third axis.'};
            app.paramTypeLabel.Text = 'Parameterization';
            app.paramTypeCombo = uidropdown(app.GridLayout3);
            app.paramTypeCombo.Items = {'Cartesian Components', 'Magnitude / Angles'};
            app.paramTypeCombo.ValueChangedFcn = createCallbackFcn(app, @paramTypeComboValueChanged, true);
            app.paramTypeCombo.Tooltip = {'Changing the parameterization converts the displayed delta-v so the burn itself is unchanged.  Optimization bounds are not converted.'};
            app.paramTypeCombo.Value = 'Cartesian Components';
            % Show the figure after all components are created
            app.lvd_AddDeltaVActionGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_AddDeltaVActionGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_AddDeltaVActionGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_AddDeltaVActionGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_AddDeltaVActionGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_AddDeltaVActionGUI)
        end
    end
end