classdef lvd_AddMassToTankActionGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_AddMassToTankActionGUI  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        uipanel1                    matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        GridLayout4                 matlab.ui.container.GridLayout
        ubText                      matlab.ui.control.EditField
        lbText                      matlab.ui.control.EditField
        optCheckbox                 matlab.ui.control.CheckBox
        text6                       matlab.ui.control.Label
        massToAddText               matlab.ui.control.EditField
        tankCombo                   matlab.ui.control.DropDown
        text5                       matlab.ui.control.Label
        text13                      matlab.ui.control.Label
        GridLayout2                 matlab.ui.container.GridLayout
        cancelButton                matlab.ui.control.Button
        saveAndCloseButton          matlab.ui.control.Button
        text2                       matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, action, lvdData)
            [tanksListStr, tanks] = lvdData.launchVehicle.getTanksListBoxStr();

            if(isempty(action.tank))
                indVal = 1;
            else
                indVal = find(tanks == action.tank, 1);
                if(isempty(indVal))
                    indVal = 1;
                end
            end

            handles.tankCombo.String = tanksListStr;
            handles.tankCombo.Value = indVal;

            handles.massToAddText.String = fullAccNum2Str(action.massToAdd);

            optVar = action.optVar;
            if(isempty(optVar))
                optVar = AddMassToTankActionOptimVar(action);
            end

            handles.optCheckbox.Value = double(optVar.getUseTfForVariable());

            [lb, ub] = optVar.getAllBndsForVariable();
            handles.lbText.String = fullAccNum2Str(lb);
            handles.ubText.String = fullAccNum2Str(ub);

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            optCheckbox_Callback(app, evt);
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            val = str2double(get(handles.massToAddText,'String'));
            enteredStr = get(handles.massToAddText,'String');
            numberName = 'Mass to Add';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);

            lwrBnd = str2double(get(handles.lbText,'String'));
            enteredStr = get(handles.lbText,'String');
            numberName = 'Mass to Add (Lower Bound)';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(lwrBnd, numberName, lb, ub, isInt, errMsg, enteredStr);

            uprBnd = str2double(get(handles.ubText,'String'));
            enteredStr = get(handles.ubText,'String');
            numberName = 'Mass to Add (Upper Bound)';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(uprBnd, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                uprBnd = str2double(get(handles.ubText,'String'));
                enteredStr = get(handles.ubText,'String');
                numberName = 'Mass to Add (Upper Bound)';
                lb = lwrBnd;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(uprBnd, numberName, lb, ub, isInt, errMsg, enteredStr);
            end
        end


        function saveAndClose(app, handles)
            action = getappdata(handles.lvd_AddMassToTankActionGUI, 'action');
            lvdData = getappdata(handles.lvd_AddMassToTankActionGUI, 'lvdData');

            [~, tanks] = lvdData.launchVehicle.getTanksListBoxStr();

            action.massToAdd = str2double(handles.massToAddText.String);
            action.tank = tanks(handles.tankCombo.Value);

            optVar = action.optVar;
            if(not(isempty(optVar))) %need to remove existing var if it exists
                lvdData.optimizer.vars.removeVariable(optVar);
            end

            %Set Opt T/F
            useTf = get(handles.optCheckbox,'Value');

            if(isempty(optVar))
                optVar = AddMassToTankActionOptimVar(action);
            end

            optVar.setUseTfForVariable(useTf);

            %Bnds
            lb = str2double(get(handles.lbText,'String'));
            ub = str2double(get(handles.ubText,'String'));

            optVar.setUseTfForVariable(true(size(lb))); %need this to get the full lb/set in there
            optVar.setBndsForVariable(lb, ub);
            optVar.setUseTfForVariable(useTf);

            lvdData.optimizer.vars.addVariable(optVar);

            app.output.output = {true};
            close(handles.lvd_AddMassToTankActionGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_AddMassToTankActionGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_AddMassToTankActionGUI (see VARARGIN)

            % Choose default command line output for lvd_AddMassToTankActionGUI
            handles.output = hObject;

            centerUIFigure(app.lvd_AddMassToTankActionGUI);
            applySelectedThemeToApp(app);
            
            action = varargin{1};
            setappdata(hObject, 'action', action);

            lvdData = varargin{2};
            setappdata(hObject, 'lvdData', lvdData);

            app.output = varargin{3};

            populateGUI(app, handles, action, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_AddMassToTankActionGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_AddMassToTankActionGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_AddMassToTankActionGUI);
        end

        % Value changed function: lbText
        function lbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of lbText as text
            %        str2double(get(hObject,'String')) returns contents of lbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Window key press function: lvd_AddMassToTankActionGUI
        function lvd_AddMassToTankActionGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_AddMassToTankActionGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_AddMassToTankActionGUI);
            end
        end

        % Value changed function: massToAddText
        function massToAddText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to massToAddText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of massToAddText as text
            %        str2double(get(hObject,'String')) returns contents of massToAddText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: optCheckbox
        function optCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.optCheckbox;
            
            % hObject    handle to optCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of optCheckbox
            if(get(hObject,'Value'))
                handles.lbText.Enable = 'on';
                handles.ubText.Enable = 'on';
            else
                handles.lbText.Enable = 'off';
                handles.ubText.Enable = 'off';
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
%                 uiresume(handles.lvd_AddMassToTankActionGUI);
                app.saveAndClose(handles);
            else
%                 msgbox(errMsg,'Invalid Tank Inputs','error');
                uialert(app.lvd_AddMassToTankActionGUI, errMsg, 'Invalid Tank Inputs', "Icon","error");
            end
        end

        % Value changed function: ubText
        function ubText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to ubText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of ubText as text
            %        str2double(get(hObject,'String')) returns contents of ubText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_AddMassToTankActionGUI = uifigure('Visible', 'off');
            app.lvd_AddMassToTankActionGUI.Name = 'Edit Action';
            app.lvd_AddMassToTankActionGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_AddMassToTankActionGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_AddMassToTankActionGUI_WindowKeyPressFcn, true);
            app.lvd_AddMassToTankActionGUI.Tag = 'lvd_AddMassToTankActionGUI';
            app.GridLayout = uigridlayout(app.lvd_AddMassToTankActionGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Action';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.text13 = uilabel(app.GridLayout3);
            app.text13.Tag = 'text13';
            app.text13.Tooltip = 'The stage this tank is attached to.';
            app.text13.Text = 'Tank';
            app.text5 = uilabel(app.GridLayout3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Mass to Add';
            app.tankCombo = uidropdown(app.GridLayout3);
            app.tankCombo.Items = {'Pop-up Menu'};
            app.tankCombo.Tag = 'tankCombo';
            app.tankCombo.Value = 'Pop-up Menu';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.massToAddText = uieditfield(app.GridLayout4, 'text');
            app.massToAddText.ValueChangedFcn = createCallbackFcn(app, @massToAddText_Callback, true);
            app.massToAddText.Tag = 'massToAddText';
            app.massToAddText.Value = '1';
            app.text6 = uilabel(app.GridLayout4);
            app.text6.Tag = 'text6';
            app.text6.Text = 'mT';
            app.optCheckbox = uicheckbox(app.GridLayout4);
            app.optCheckbox.ValueChangedFcn = createCallbackFcn(app, @optCheckbox_Callback, true);
            app.optCheckbox.Tag = 'optCheckbox';
            app.optCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.optCheckbox.Text = 'Opt?';
            app.optCheckbox.Value = true;
            app.lbText = uieditfield(app.GridLayout4, 'text');
            app.lbText.ValueChangedFcn = createCallbackFcn(app, @lbText_Callback, true);
            app.lbText.Tag = 'lbText';
            app.lbText.Tooltip = 'Parameter lower bound.';
            app.lbText.Value = '0.0';
            app.ubText = uieditfield(app.GridLayout4, 'text');
            app.ubText.ValueChangedFcn = createCallbackFcn(app, @ubText_Callback, true);
            app.ubText.Tag = 'ubText';
            app.ubText.Tooltip = 'Parameter upper bound.';
            app.ubText.Value = '0.0';
            % Show the figure after all components are created
            app.lvd_AddMassToTankActionGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_AddMassToTankActionGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_AddMassToTankActionGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_AddMassToTankActionGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_AddMassToTankActionGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_AddMassToTankActionGUI)
        end
    end
end