classdef lvd_EditActionAddValueToLvdPluginVarValueGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditActionSetPluginVarValueGUI  matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        uipanel1            matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        GridLayout4         matlab.ui.container.GridLayout
        offsetValueText     matlab.ui.control.EditField
        text6               matlab.ui.control.Label
        PluginVarsCombo     matlab.ui.control.DropDown
        text5               matlab.ui.control.Label
        text3               matlab.ui.control.Label
        GridLayout2         matlab.ui.container.GridLayout
        cancelButton        matlab.ui.control.Button
        saveAndCloseButton  matlab.ui.control.Button
        text2               matlab.ui.control.Label
    end


    properties (Access = private)
        action AddValueToLvdPluginVarValueAction
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app)
            lvdData = app.action.event.lvdData;
            [listBoxStr, pluginVars] = lvdData.pluginVars.getListboxStr();
            
            app.PluginVarsCombo.Items = listBoxStr;
            app.PluginVarsCombo.ItemsData = pluginVars;
            app.PluginVarsCombo.Value = app.action.pluginVar;

            app.offsetValueText.Value = fullAccNum2Str(app.action.pluginValueOffset);
        end

        function errMsg = validateInputs(app)
            errMsg = {};

            val = str2double(app.offsetValueText.Value);
            enteredStr = app.offsetValueText.Value;
            numberName = 'Value Offset';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
        end

        function saveAndClose(app)
            app.action.pluginVar = app.PluginVarsCombo.Value;

            app.action.pluginValueOffset                     = str2double(app.offsetValueText.Value);

            app.output.output = {true};
            delete(app);
        end

        function validateSaveAndClose(app)
            errMsg = app.validateInputs();

            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.lvd_EditActionSetPluginVarValueGUI, errMsg, 'Invalid Inputs', "Icon","error");
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditActionSetTankToTankConnFlowRateGUI_OpeningFcn(app, action, output)
            centerUIFigure(app.lvd_EditActionSetPluginVarValueGUI);
            applySelectedThemeToApp(app);

            app.action = action;
            app.output = output;

            app.populateGUI();

            % UIWAIT makes lvd_EditActionSetTankToTankConnFlowRateGUI wait for user response (see UIRESUME)
            uiwait(app.lvd_EditActionSetPluginVarValueGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            close(app.lvd_EditActionSetPluginVarValueGUI);
        end

        % Value changed function: offsetValueText
        function offsetValueText_Callback(app, event)
            app.offsetValueText.Value = attemptStrEval(app.offsetValueText.Value);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            app.validateSaveAndClose();
        end

        % Window key press function: lvd_EditActionSetPluginVarValueGUI
        function lvd_EditActionSetPluginVarValueGUIWindowKeyPress(app, event)
            key = event.Key;
            switch(key)
                case 'return'
                    app.validateSaveAndClose();
                case 'enter'
                    app.validateSaveAndClose();
                case 'escape'
                    close(app.lvd_EditActionSetPluginVarValueGUI);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditActionSetPluginVarValueGUI = uifigure('Visible', 'off');
            app.lvd_EditActionSetPluginVarValueGUI.Name = 'Edit Action';
            app.lvd_EditActionSetPluginVarValueGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditActionSetPluginVarValueGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditActionSetPluginVarValueGUIWindowKeyPress, true);
            app.lvd_EditActionSetPluginVarValueGUI.Tag = 'lvd_EditActionSetPluginVarValueGUI';
            app.GridLayout = uigridlayout(app.lvd_EditActionSetPluginVarValueGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Action';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.text3 = uilabel(app.GridLayout3);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Plugin Variable';
            app.text5 = uilabel(app.GridLayout3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Value Offset';
            app.PluginVarsCombo = uidropdown(app.GridLayout3);
            app.PluginVarsCombo.Items = {'Pop-up Menu'};
            app.PluginVarsCombo.Tag = 'refConnCombo';
            app.PluginVarsCombo.Value = 'Pop-up Menu';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.text6 = uilabel(app.GridLayout4);
            app.text6.Tag = 'text6';
            app.text6.Tooltip = {'The unit of plugin variables is determined by the user directly.'};
            app.text6.Text = '<unit>';
            app.offsetValueText = uieditfield(app.GridLayout4, 'text');
            app.offsetValueText.ValueChangedFcn = createCallbackFcn(app, @offsetValueText_Callback, true);
            app.offsetValueText.Tag = 'flowRateText';
            app.offsetValueText.Tooltip = 'Rate of propellent flow from source tank to destination tank.  Must be positive.  Change with an event action.';
            app.offsetValueText.Value = '1';
            % Show the figure after all components are created
            app.lvd_EditActionSetPluginVarValueGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditActionAddValueToLvdPluginVarValueGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditActionSetPluginVarValueGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditActionSetTankToTankConnFlowRateGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditActionSetPluginVarValueGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditActionSetPluginVarValueGUI)
        end
    end
end