classdef lvd_EditActionConditionalGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditActionConditionalUIFigure   matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        SaveandCloseButton              matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout9                     matlab.ui.container.GridLayout
        GridLayout10                    matlab.ui.container.GridLayout
        ConditionalTypeDropDown         matlab.ui.control.DropDown
        ConditionalTypeLabel            matlab.ui.control.Label
        ConditionalPropertiesPanel      matlab.ui.container.Panel
        ConditionalPropsGridLayout      matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        GridLayout13                    matlab.ui.container.GridLayout
        GridLayout17                    matlab.ui.container.GridLayout
        ToleranceUnitLabel              matlab.ui.control.Label
        ToleranceText                   matlab.ui.control.EditField
        ToleranceLabel                  matlab.ui.control.Label
        QuantityComparisonPanel         matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        CompareEventNodeDropDown        matlab.ui.control.DropDown
        CompareEventNodeLabel           matlab.ui.control.Label
        CompareEventDropDown            matlab.ui.control.DropDown
        CompareEventLabel               matlab.ui.control.Label
        QuantComparisonReferenceFrameSelector  referenceFrameSelectComp
        QuantityComparisonDropDown      matlab.ui.control.DropDown
        QuantityLabel_2                 matlab.ui.control.Label
        NumericConstantComparisonPanel  matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        GridLayout6                     matlab.ui.container.GridLayout
        ComparisonValueText             matlab.ui.control.EditField
        ComparisonValueUnitLabel        matlab.ui.control.Label
        ComparisonValueLabel            matlab.ui.control.Label
        GridLayout15                    matlab.ui.container.GridLayout
        CompareAgainstDropDown          matlab.ui.control.DropDown
        CompareAgainstLabel             matlab.ui.control.Label
        GridLayout14                    matlab.ui.container.GridLayout
        ComparisonTypeDropdown          matlab.ui.control.DropDown
        ComparisonTypeLabel             matlab.ui.control.Label
        ComparisonQuantityPanel         matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        QuantityFrameSelector           referenceFrameSelectComp
        QuantityDropdown                matlab.ui.control.DropDown
        QuantityLabel                   matlab.ui.control.Label
        TextLabel                       matlab.ui.control.Label
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        RemoveConditionalButton         matlab.ui.control.Button
        AddConditionalButton            matlab.ui.control.Button
        ConditionalTree                 matlab.ui.container.Tree
        text2                           matlab.ui.control.Label
    end

    properties (Access = private)
        conditional AbstractActionConditional
        lvdData LvdData
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app)
            app.populateTree();

            [listboxStr, m] = ConditionalTypeEnum.getListBoxStr();
            app.ConditionalTypeDropDown.Items = listboxStr;
            app.ConditionalTypeDropDown.ItemsData = m;

            taskList = lvd_getGraphAnalysisTaskList(app.lvdData, getLvdGAExcludeList());
            app.QuantityDropdown.Items = taskList;
            app.QuantityComparisonDropDown.Items = taskList;
            
            [listboxStr, m] = ComparisonTypeEnum.getListBoxStr();
            app.ComparisonTypeDropdown.Items = listboxStr;
            app.ComparisonTypeDropdown.ItemsData = m;

            [listBoxStr, m] = CompareAgainstEnum.getListBoxStr();
            app.CompareAgainstDropDown.Items = listBoxStr;
            app.CompareAgainstDropDown.ItemsData = m;

            [listBoxStr, m] = ConstraintStateComparisonNodeEnum.getListBoxStr();
            app.CompareEventNodeDropDown.Items = listBoxStr;
            app.CompareEventNodeDropDown.ItemsData = m;
            app.populateCompareEventDropDown();

            app.QuantityFrameSelector.initializeWithFrames(app.lvdData);
            app.QuantComparisonReferenceFrameSelector.initializeWithFrames(app.lvdData);
            app.populateConditionalArea();

            addlistener(app.QuantityFrameSelector, 'FrameChanged', @(src,evt) app.handleQuantityFrameChanged(src,evt));
        end

        function populateTree(app)
            treeChildren = app.ConditionalTree.Children;
            treeChildren.delete();

            app.conditional.getTreeNodes(app.ConditionalTree);
            expand(app.ConditionalTree, 'all');

            if(isempty(app.ConditionalTree.SelectedNodes))
                app.ConditionalTree.SelectedNodes = app.ConditionalTree.Children(1);
            end
        end

        function populateConditionalArea(app)
            node = app.ConditionalTree.SelectedNodes;
            thisConditional = node.NodeData;

            app.ConditionalTypeDropDown.Value = thisConditional.enum;
            
            if(thisConditional.enum == ConditionalTypeEnum.QuantityComparison)
                app.QuantityDropdown.Enable = true;

                if(not(ismember(thisConditional.task.taskStr, app.QuantityDropdown.Items)))
                    taskList = lvd_getGraphAnalysisTaskList(app.lvdData, getLvdGAExcludeList());
                    thisConditional.task.taskStr = taskList{1};
                end

                app.QuantityDropdown.Value = thisConditional.task.taskStr;

                app.ComparisonTypeDropdown.Enable = true;
                app.ComparisonTypeDropdown.Value = thisConditional.comparisonType;

                app.CompareAgainstDropDown.Enable = true;
                app.CompareAgainstDropDown.Value = thisConditional.compareAgainst;

                app.ComparisonValueText.Enable = true;
                app.ComparisonValueText.Value = fullAccNum2Str(thisConditional.tgtValue);

                app.ToleranceText.Enable = true;
                app.ToleranceText.Value = fullAccNum2Str(thisConditional.tol);

                stateLogEntry = app.lvdData.initStateModel.getInitialStateLogEntry();
                [~, unitStr] = thisConditional.evaluateConditional(stateLogEntry);
                app.ComparisonValueUnitLabel.Text = unitStr;
                app.ComparisonValueUnitLabel.Tooltip = unitStr;
                app.ToleranceUnitLabel.Text = unitStr;
                app.ToleranceUnitLabel.Tooltip = unitStr;

                if(isempty(thisConditional.quantCompareTaskStr))
                    lvdTaskList = lvd_getGraphAnalysisTaskList(app.lvdData, getLvdGAExcludeList());
                    thisConditional.quantCompareTaskStr = lvdTaskList{1};
                end

                app.QuantityComparisonDropDown.Enable = true;

                if(not(ismember(thisConditional.quantCompareTaskStr, app.QuantityComparisonDropDown.Items)))
                    taskList = lvd_getGraphAnalysisTaskList(app.lvdData, getLvdGAExcludeList());
                    thisConditional.quantCompareTaskStr = taskList{1};
                end
                app.QuantityComparisonDropDown.Value = thisConditional.quantCompareTaskStr;

                app.QuantityFrameSelector.Enabled = true;
                if(isempty(thisConditional.frame))
                    thisConditional.frame = app.lvdData.initStateModel.centralBody.getBodyCenteredInertialFrame();
                end
                app.QuantityFrameSelector.setSelectedFrame(thisConditional.frame);

                app.QuantComparisonReferenceFrameSelector.Enabled = true;
                if(isempty(thisConditional.quantCompareFrame))
                    thisConditional.quantCompareFrame = app.lvdData.initStateModel.centralBody.getBodyCenteredInertialFrame();
                end
                app.QuantComparisonReferenceFrameSelector.setSelectedFrame(thisConditional.quantCompareFrame);

                %Quantity-at-event comparison controls
                app.populateCompareEventDropDown();
                scriptEvts = app.lvdData.script.evts;
                if(isempty(thisConditional.compareEvent) && not(isempty(scriptEvts)))
                    thisConditional.compareEvent = scriptEvts(1);
                end
                if(not(isempty(thisConditional.compareEvent)) && any(scriptEvts == thisConditional.compareEvent))
                    app.CompareEventDropDown.Value = thisConditional.compareEvent;
                end
                app.CompareEventNodeDropDown.Value = thisConditional.compareEventNode;

            else
                app.QuantityDropdown.Enable = false;
                app.ComparisonTypeDropdown.Enable = false;
                app.CompareAgainstDropDown.Enable = false;
                app.ComparisonValueText.Enable = false;
                app.ToleranceText.Enable = false;

                app.ComparisonValueText.Value = "";
                app.ToleranceText.Value = "";

                app.ComparisonValueUnitLabel.Text = "";
                app.ComparisonValueUnitLabel.Tooltip = "";
                app.ToleranceUnitLabel.Text = "";
                app.ToleranceUnitLabel.Tooltip = "";

                app.QuantityComparisonDropDown.Enable = false;

                app.QuantityFrameSelector.Enabled = false;
                app.QuantComparisonReferenceFrameSelector.Enabled = false;
            end

            app.TextLabel.Text = thisConditional.getConditionalString();
            app.TextLabel.Tooltip = app.TextLabel.Text;

            app.setAddRemoveConditionalButtonEnables();
            app.setPanelEnablesByComparisonAgainst();
        end

        function setAddRemoveConditionalButtonEnables(app)
            node = app.ConditionalTree.SelectedNodes;
            thisConditional = node.NodeData;

            allNodes = app.getAllTreeNodes();
            if(app.isContainerConditional(thisConditional))
                app.AddConditionalButton.Enable = app.canAddToConditional(thisConditional);
                if(numel(allNodes) > 1)
                    app.RemoveConditionalButton.Enable = true;
                else
                    app.RemoveConditionalButton.Enable = false;
                end

            elseif(thisConditional ~= app.conditional)
                parentNode = node.Parent;
                parentNodeConditional = parentNode.NodeData;

                if(app.isContainerConditional(parentNodeConditional))
                    app.AddConditionalButton.Enable = app.canAddToConditional(parentNodeConditional);
                    if(numel(allNodes) > 1)
                        app.RemoveConditionalButton.Enable = true;
                    else
                        app.RemoveConditionalButton.Enable = false;
                    end
                end

            else
                app.AddConditionalButton.Enable = false;
                app.RemoveConditionalButton.Enable = false;
            end
        end

        function setPanelEnablesByComparisonAgainst(app)
            enum = app.CompareAgainstDropDown.Value;
            if(enum == CompareAgainstEnum.NumericConstant)
                app.NumericConstantComparisonPanel.Enable = true;
                app.QuantityComparisonPanel.Enable = false;
                app.setCompareEventControlsEnable(false);

            elseif(enum == CompareAgainstEnum.GaTaskQuantity)
                app.NumericConstantComparisonPanel.Enable = false;
                app.QuantityComparisonPanel.Enable = true;
                app.setCompareEventControlsEnable(false);

            elseif(enum == CompareAgainstEnum.EventQuantity)
                app.NumericConstantComparisonPanel.Enable = false;
                app.QuantityComparisonPanel.Enable = true;
                app.setCompareEventControlsEnable(true);

            else
                error('Uknown comparison against type: %s', enum.name);
            end
        end

        function onCompareEventChanged(app, ~, ~)
            node = app.ConditionalTree.SelectedNodes;
            thisConditional = node.NodeData;

            if(thisConditional.enum == ConditionalTypeEnum.QuantityComparison)
                selEvt = app.CompareEventDropDown.Value;
                if(isa(selEvt, 'LaunchVehicleEvent'))
                    thisConditional.compareEvent = selEvt;
                end
            end

            app.repopulateTreeAndSetToSelectedConditional(thisConditional);
        end

        function onCompareEventNodeChanged(app, ~, ~)
            node = app.ConditionalTree.SelectedNodes;
            thisConditional = node.NodeData;

            if(thisConditional.enum == ConditionalTypeEnum.QuantityComparison)
                thisConditional.compareEventNode = app.CompareEventNodeDropDown.Value;
            end

            app.repopulateTreeAndSetToSelectedConditional(thisConditional);
        end

        function setCompareEventControlsEnable(app, tf)
            app.CompareEventLabel.Enable = tf;
            app.CompareEventDropDown.Enable = tf;
            app.CompareEventNodeLabel.Enable = tf;
            app.CompareEventNodeDropDown.Enable = tf;
        end

        function populateCompareEventDropDown(app)
            %Lists the sequential events of the mission script.
            [listboxStr, events] = app.lvdData.script.getListboxStr();

            if(isempty(events))
                app.CompareEventDropDown.Items = {'<No Events>'};
                app.CompareEventDropDown.ItemsData = {[]};
            else
                app.CompareEventDropDown.Items = listboxStr;
                app.CompareEventDropDown.ItemsData = events;
            end
        end

        function tf = isContainerConditional(~, cond)
            %AND, OR, and NOT conditionals hold child conditionals.
            tf = cond.enum == ConditionalTypeEnum.LogicalAnd || ...
                 cond.enum == ConditionalTypeEnum.LogicalOr || ...
                 cond.enum == ConditionalTypeEnum.LogicalNot;
        end

        function tf = canAddToConditional(~, cond)
            %AND/OR take any number of children; NOT takes exactly one.
            tf = cond.enum == ConditionalTypeEnum.LogicalAnd || ...
                 cond.enum == ConditionalTypeEnum.LogicalOr || ...
                 (cond.enum == ConditionalTypeEnum.LogicalNot && not(cond.hasConditional()));
        end

        function saveAndClose(app)
            app.output.output = {app.conditional, true};
            close(app.EditActionConditionalUIFigure);
        end

        function nodes = getAllTreeNodes(app)
            nodes = app.ConditionalTree.Children;
            nodes = nodes(:)';

            for(i=1:numel(nodes))
                newNodes = app.getNodeChildren(nodes(i));
                nodes = horzcat(nodes, newNodes(:)'); %#ok<AGROW>
            end
        end

        function nodes = getNodeChildren(app, node)
            nodes = node.Children;
            nodes = nodes(:)';

            for(i=1:numel(nodes))
                nodes = horzcat(nodes, app.getNodeChildren(nodes(i))); %#ok<AGROW>
            end

            nodes = nodes(:)';
        end

        function repopulateTreeAndSetToSelectedConditional(app, cond)
            app.populateTree();

            nC = app.getAllTreeNodes();
            for(i=1:numel(nC))
                node = nC(i);

                if(node.NodeData == cond)
                    app.ConditionalTree.SelectedNodes = nC(i);

                    break;
                end
            end

            app.populateConditionalArea();
        end

        function handleQuantityFrameChanged(app, src, evt)
            node = app.ConditionalTree.SelectedNodes;
            thisConditional = node.NodeData;

            if(thisConditional.enum == ConditionalTypeEnum.QuantityComparison)
                thisConditional.frame = app.QuantityFrameSelector.getSelectedFrame();
                thisConditional.task.frame = thisConditional.frame;
            end

            app.TextLabel.Text = thisConditional.getConditionalString();
            app.TextLabel.Tooltip = app.TextLabel.Text;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, conditional, lvdData, output)
            centerUIFigure(app.EditActionConditionalUIFigure);
            applySelectedThemeToApp(app);

            app.conditional = conditional;
            app.lvdData = lvdData;
            app.output = output;

            app.populateGUI();

            uiwait(app.EditActionConditionalUIFigure);
        end

        % Button pushed function: AddConditionalButton
        function AddConditionalButtonPushed(app, event)
            node = app.ConditionalTree.SelectedNodes;
            thisConditional = node.NodeData;

            if(app.canAddToConditional(thisConditional))
                newConditional = AlwaysTrueActionCondition();
                thisConditional.addConditional(newConditional);

                app.repopulateTreeAndSetToSelectedConditional(newConditional);

            elseif(thisConditional ~= app.conditional)
                parentNode = node.Parent;
                parentNodeConditional = parentNode.NodeData;

                if(app.canAddToConditional(parentNodeConditional))
                    newConditional = AlwaysTrueActionCondition();
                    parentNodeConditional.addConditional(newConditional);
    
                    app.repopulateTreeAndSetToSelectedConditional(newConditional);
                end
            end
        end

        % Button pushed function: RemoveConditionalButton
        function RemoveConditionalButtonPushed(app, event)
            node = app.ConditionalTree.SelectedNodes;            
            thisConditional = node.NodeData;

            if(thisConditional == app.conditional)
                newConditional = AlwaysTrueActionCondition();
                app.conditional = newConditional;

                app.repopulateTreeAndSetToSelectedConditional(newConditional);
            else
                parentNode = node.Parent;
                parentNodeConditional = parentNode.NodeData;

                if(app.isContainerConditional(parentNodeConditional))
                    parentNodeConditional.removeConditional(thisConditional);

                    app.repopulateTreeAndSetToSelectedConditional(parentNodeConditional);
                end
            end
        end

        % Value changed function: ComparisonValueText
        function ComparisonValueTextValueChanged(app, event)
            app.ComparisonValueText.Value = attemptStrEval(app.ComparisonValueText.Value);

            value = str2double(app.ComparisonValueText.Value);
            enteredStr = app.ComparisonValueText.Value;
            numberName = 'Comparison Value';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, {}, enteredStr);

            if(isempty(errMsg))
                node = app.ConditionalTree.SelectedNodes;
                thisConditional = node.NodeData;
                thisConditional.tgtValue = str2double(app.ComparisonValueText.Value);

                app.repopulateTreeAndSetToSelectedConditional(thisConditional);
            else
                uialert(app.EditActionConditionalUIFigure, errMsg, 'Invalid Comparison Value','Icon','error', 'Modal',true);
            end
        end

        % Value changed function: ToleranceText
        function ToleranceTextValueChanged(app, event)
            app.ToleranceText.Value = attemptStrEval(app.ToleranceText.Value);

            value = str2double(app.ToleranceText.Value);
            enteredStr = app.ToleranceText.Value;
            numberName = 'Comparison Tolerance';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, {}, enteredStr);

            if(isempty(errMsg))
                node = app.ConditionalTree.SelectedNodes;
                thisConditional = node.NodeData;
                thisConditional.tol = str2double(app.ToleranceText.Value);

                app.repopulateTreeAndSetToSelectedConditional(thisConditional);
            else
                uialert(app.EditActionConditionalUIFigure, errMsg, 'Invalid Comparison Tolerance','Icon','error', 'Modal',true);
            end            
        end

        % Value changed function: QuantityDropdown
        function QuantityDropdownValueChanged(app, event)
            node = app.ConditionalTree.SelectedNodes;
            thisConditional = node.NodeData;
            
            if(thisConditional.enum == ConditionalTypeEnum.QuantityComparison)
                thisConditional.task.taskStr = app.QuantityDropdown.Value;
            end

            app.repopulateTreeAndSetToSelectedConditional(thisConditional);
        end

        % Value changed function: ComparisonTypeDropdown
        function ComparisonTypeDropdownValueChanged(app, event)
            node = app.ConditionalTree.SelectedNodes;
            thisConditional = node.NodeData;
            
            if(thisConditional.enum == ConditionalTypeEnum.QuantityComparison)
                thisConditional.comparisonType = app.ComparisonTypeDropdown.Value;
            end

            app.repopulateTreeAndSetToSelectedConditional(thisConditional);
        end

        % Value changed function: ConditionalTypeDropDown
        function ConditionalTypeDropDownValueChanged(app, event)
            newConditionalType = app.ConditionalTypeDropDown.Value;
            
            node = app.ConditionalTree.SelectedNodes;
            thisConditional = node.NodeData;

            switch newConditionalType
                case ConditionalTypeEnum.QuantityComparison
                    frame = app.lvdData.initStateModel.centralBody.getBodyCenteredInertialFrame(); %TODO this needs to be a user set frame
                    task = GraphicalAnalysisTask('Altitude', frame);
                    newConditional = QuantityComparisonActionCondition(task, 0, ComparisonTypeEnum.Equals, 0.0, frame);

                case ConditionalTypeEnum.AlwaysTrue
                    newConditional = AlwaysTrueActionCondition();

                case ConditionalTypeEnum.AlwaysFalse
                    newConditional = AlwaysFalseActionCondition();

                case ConditionalTypeEnum.LogicalAnd
                    newConditional = LogicalAndActionConditional(AbstractActionConditional.empty(1,0));

                case ConditionalTypeEnum.LogicalOr
                    newConditional = LogicalOrActionConditional(AbstractActionConditional.empty(1,0));

                case ConditionalTypeEnum.LogicalNot
                    newConditional = LogicalNotActionConditional(AbstractActionConditional.empty(1,0));

                otherwise
                    error('Unknown conditonal type: %s', newConditionalType.name);
            end

            if(thisConditional == app.conditional)
                app.conditional = newConditional;

            else
                parentNode = node.Parent;
                parentNodeConditional = parentNode.NodeData;

                parentNodeConditional.substituteConditional(thisConditional, newConditional);

            end

            app.populateTree();

            nC = app.getAllTreeNodes();
            for(i=1:numel(nC))
                node = nC(i);

                if(node.NodeData == newConditional)
                    app.ConditionalTree.SelectedNodes = node;

                    break;
                end
            end

            app.populateConditionalArea();
        end

        % Button pushed function: SaveandCloseButton
        function SaveandCloseButtonPushed(app, event)
            app.saveAndClose();
        end

        % Selection changed function: ConditionalTree
        function ConditionalTreeSelectionChanged(app, event)
            app.populateConditionalArea();
        end

        % Value changed function: QuantityComparisonDropDown
        function QuantityComparisonDropDownValueChanged(app, event)
            node = app.ConditionalTree.SelectedNodes;
            thisConditional = node.NodeData;
            
            if(thisConditional.enum == ConditionalTypeEnum.QuantityComparison)
                thisConditional.quantCompareTaskStr = app.QuantityComparisonDropDown.Value;
            end

            app.repopulateTreeAndSetToSelectedConditional(thisConditional);
        end

        % Value changed function: CompareAgainstDropDown
        function CompareAgainstDropDownValueChanged(app, event)
            node = app.ConditionalTree.SelectedNodes;
            thisConditional = node.NodeData;

            if(thisConditional.enum == ConditionalTypeEnum.QuantityComparison)
                thisConditional.compareAgainst = app.CompareAgainstDropDown.Value;
            end
            
            app.setPanelEnablesByComparisonAgainst();
            app.repopulateTreeAndSetToSelectedConditional(thisConditional);
        end

        % Value changed function: CompareEventDropDown
        function CompareEventDropDownValueChanged(app, event)
            app.onCompareEventChanged(app.CompareEventDropDown, event);
        end

        % Value changed function: CompareEventNodeDropDown
        function CompareEventNodeDropDownValueChanged(app, event)
            app.onCompareEventNodeChanged(app.CompareEventNodeDropDown, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditActionConditionalUIFigure = uifigure('Visible', 'off');
            app.EditActionConditionalUIFigure.Name = 'Edit Action Conditional';
            app.GridLayout = uigridlayout(app.EditActionConditionalUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Action Conditional';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ConditionalTree = uitree(app.GridLayout3);
            app.ConditionalTree.SelectionChangedFcn = createCallbackFcn(app, @ConditionalTreeSelectionChanged, true);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.AddConditionalButton = uibutton(app.GridLayout4, 'push');
            app.AddConditionalButton.ButtonPushedFcn = createCallbackFcn(app, @AddConditionalButtonPushed, true);
            app.AddConditionalButton.Icon = 'add.png';
            app.AddConditionalButton.Tooltip = {'Adds a condition to an AND or OR conditional.'};
            app.AddConditionalButton.Text = 'Add Condition';
            app.RemoveConditionalButton = uibutton(app.GridLayout4, 'push');
            app.RemoveConditionalButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveConditionalButtonPushed, true);
            app.RemoveConditionalButton.Icon = 'remove.png';
            app.RemoveConditionalButton.Tooltip = {'Removes a condition to an AND or OR conditional.'};
            app.RemoveConditionalButton.Text = 'Remove Condition';
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.ConditionalPropertiesPanel = uipanel(app.GridLayout9);
            app.ConditionalPropertiesPanel.Title = 'Conditional Properties';
            app.ConditionalPropsGridLayout = uigridlayout(app.ConditionalPropertiesPanel);
            app.GridLayout8 = uigridlayout(app.ConditionalPropsGridLayout);
            app.TextLabel = uilabel(app.GridLayout8);
            app.TextLabel.Text = 'Text';
            app.GridLayout13 = uigridlayout(app.GridLayout8);
            app.ComparisonQuantityPanel = uipanel(app.GridLayout13);
            app.ComparisonQuantityPanel.Title = 'Comparison Quantity';
            app.GridLayout12 = uigridlayout(app.ComparisonQuantityPanel);
            app.QuantityLabel = uilabel(app.GridLayout12);
            app.QuantityLabel.Text = 'Quantity';
            app.QuantityDropdown = uidropdown(app.GridLayout12);
            app.QuantityDropdown.ValueChangedFcn = createCallbackFcn(app, @QuantityDropdownValueChanged, true);
            app.QuantityFrameSelector = referenceFrameSelectComp(app.GridLayout12);
            app.GridLayout14 = uigridlayout(app.GridLayout13);
            app.ComparisonTypeLabel = uilabel(app.GridLayout14);
            app.ComparisonTypeLabel.Text = 'Comparison Type';
            app.ComparisonTypeDropdown = uidropdown(app.GridLayout14);
            app.ComparisonTypeDropdown.ValueChangedFcn = createCallbackFcn(app, @ComparisonTypeDropdownValueChanged, true);
            app.GridLayout15 = uigridlayout(app.GridLayout13);
            app.CompareAgainstLabel = uilabel(app.GridLayout15);
            app.CompareAgainstLabel.Text = 'Compare Against';
            app.CompareAgainstDropDown = uidropdown(app.GridLayout15);
            app.CompareAgainstDropDown.ValueChangedFcn = createCallbackFcn(app, @CompareAgainstDropDownValueChanged, true);
            app.NumericConstantComparisonPanel = uipanel(app.GridLayout13);
            app.NumericConstantComparisonPanel.Title = 'Numeric Constant Comparison';
            app.GridLayout11 = uigridlayout(app.NumericConstantComparisonPanel);
            app.ComparisonValueLabel = uilabel(app.GridLayout11);
            app.ComparisonValueLabel.Text = 'Comparison Value';
            app.GridLayout6 = uigridlayout(app.GridLayout11);
            app.ComparisonValueUnitLabel = uilabel(app.GridLayout6);
            app.ComparisonValueUnitLabel.Tag = 'utUnitLabel';
            app.ComparisonValueUnitLabel.Tooltip = {'Plugin variable units  are determined by the user directly.'};
            app.ComparisonValueUnitLabel.Text = '<unit>';
            app.ComparisonValueText = uieditfield(app.GridLayout6, 'text');
            app.ComparisonValueText.ValueChangedFcn = createCallbackFcn(app, @ComparisonValueTextValueChanged, true);
            app.QuantityComparisonPanel = uipanel(app.GridLayout13);
            app.QuantityComparisonPanel.Title = 'Quantity Comparison';
            app.GridLayout16 = uigridlayout(app.QuantityComparisonPanel);
            app.QuantityLabel_2 = uilabel(app.GridLayout16);
            app.QuantityLabel_2.Text = 'Quantity';
            app.QuantityComparisonDropDown = uidropdown(app.GridLayout16);
            app.QuantityComparisonDropDown.ValueChangedFcn = createCallbackFcn(app, @QuantityComparisonDropDownValueChanged, true);
            app.QuantComparisonReferenceFrameSelector = referenceFrameSelectComp(app.GridLayout16);
            app.CompareEventLabel = uilabel(app.GridLayout16);
            app.CompareEventLabel.Text = 'At Event';
            app.CompareEventDropDown = uidropdown(app.GridLayout16);
            app.CompareEventDropDown.Items = {'<No Events>'};
            app.CompareEventDropDown.ValueChangedFcn = createCallbackFcn(app, @CompareEventDropDownValueChanged, true);
            app.CompareEventDropDown.Tooltip = {'For "Quantity at Event" comparisons: the event whose logged state the comparison quantity is evaluated at.  The event must have been propagated before this action runs.'};
            app.CompareEventDropDown.Value = '<No Events>';
            app.CompareEventNodeLabel = uilabel(app.GridLayout16);
            app.CompareEventNodeLabel.Text = 'Event State';
            app.CompareEventNodeDropDown = uidropdown(app.GridLayout16);
            app.CompareEventNodeDropDown.Items = {'Final State', 'Initial State'};
            app.CompareEventNodeDropDown.ValueChangedFcn = createCallbackFcn(app, @CompareEventNodeDropDownValueChanged, true);
            app.CompareEventNodeDropDown.Tooltip = {'Whether the comparison quantity is taken from the initial or the final state of the selected event.'};
            app.CompareEventNodeDropDown.Value = 'Final State';
            app.GridLayout17 = uigridlayout(app.GridLayout13);
            app.ToleranceLabel = uilabel(app.GridLayout17);
            app.ToleranceLabel.Text = 'Tolerance';
            app.ToleranceText = uieditfield(app.GridLayout17, 'text');
            app.ToleranceText.ValueChangedFcn = createCallbackFcn(app, @ToleranceTextValueChanged, true);
            app.ToleranceText.Tooltip = {'The tolerance on the comparison value, in units of the quantity above.'};
            app.ToleranceUnitLabel = uilabel(app.GridLayout17);
            app.ToleranceUnitLabel.Tag = 'utUnitLabel';
            app.ToleranceUnitLabel.Tooltip = {'Plugin variable units  are determined by the user directly.'};
            app.ToleranceUnitLabel.Text = '<unit>';
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.ConditionalTypeLabel = uilabel(app.GridLayout10);
            app.ConditionalTypeLabel.Text = 'Conditional Type';
            app.ConditionalTypeDropDown = uidropdown(app.GridLayout10);
            app.ConditionalTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ConditionalTypeDropDownValueChanged, true);
            app.SaveandCloseButton = uibutton(app.GridLayout, 'push');
            app.SaveandCloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandCloseButtonPushed, true);
            app.SaveandCloseButton.Icon = 'save-file.png';
            app.SaveandCloseButton.Text = 'Save and Close';
            % Show the figure after all components are created
            app.EditActionConditionalUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditActionConditionalGUI_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EditActionConditionalUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditActionConditionalUIFigure)
        end
    end
end