classdef lvd_EditActionResetExtremumValueGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditActionResetExtremumValueGUI  matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        uipanel1            matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        refExtremumCombo    matlab.ui.control.DropDown
        text3               matlab.ui.control.Label
        GridLayout2         matlab.ui.container.GridLayout
        cancelButton        matlab.ui.control.Button
        saveAndCloseButton  matlab.ui.control.Button
        text2               matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, action, lv)
            [exListStr, extrema] = lv.getExtremaListBoxStr();
            if(not(isempty(exListStr)))
                set(handles.refExtremumCombo,'String',exListStr);
            else
                set(handles.refExtremumCombo,'String',' ');
            end

            if(not(isempty(action.extremum)))
                ind = find(extrema == action.extremum,1,'first');
            else
                ind = [];
            end

            if(not(isempty(ind)))
                set(handles.refExtremumCombo,'Value',ind);
            end
        end


        function saveAndClose(app, handles)
            action = getappdata(handles.lvd_EditActionResetExtremumValueGUI,'action');
            lv = getappdata(handles.lvd_EditActionResetExtremumValueGUI,'lv');
            
            [~, extrema] = lv.getExtremaListBoxStr();
            if(not(isempty(extrema)))
                ind = get(handles.refExtremumCombo,'Value');
                action.extremum = extrema(ind);
            end
            
            app.output.output = {true};
            close(handles.lvd_EditActionResetExtremumValueGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditActionResetExtremumValueGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditActionResetExtremumValueGUI (see VARARGIN)

            % Choose default command line output for lvd_EditActionResetExtremumValueGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            action = varargin{1};
            setappdata(hObject,'action',action);

            lv = varargin{2};
            setappdata(hObject,'lv',lv);

            app.output = varargin{3};

            populateGUI(app, handles, action, lv);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditActionResetExtremumValueGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditActionResetExtremumValueGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditActionResetExtremumValueGUI);
        end

        % Window key press function: lvd_EditActionResetExtremumValueGUI
        function lvd_EditActionResetExtremumValueGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditActionResetExtremumValueGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditActionResetExtremumValueGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %                 uiresume(handles.lvd_EditActionResetExtremumValueGUI);
            app.saveAndClose(handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditActionResetExtremumValueGUI = uifigure('Visible', 'off');
            app.lvd_EditActionResetExtremumValueGUI.Name = 'Edit Action';
            app.lvd_EditActionResetExtremumValueGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditActionResetExtremumValueGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditActionResetExtremumValueGUI_WindowKeyPressFcn, true);
            app.lvd_EditActionResetExtremumValueGUI.Tag = 'lvd_EditActionResetExtremumValueGUI';
            app.GridLayout = uigridlayout(app.lvd_EditActionResetExtremumValueGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Action';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.text3 = uilabel(app.GridLayout3);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Ref. Extremum';
            app.refExtremumCombo = uidropdown(app.GridLayout3);
            app.refExtremumCombo.Items = {'Pop-up Menu'};
            app.refExtremumCombo.Tag = 'refExtremumCombo';
            app.refExtremumCombo.Value = 'Pop-up Menu';
            % Show the figure after all components are created
            app.lvd_EditActionResetExtremumValueGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditActionResetExtremumValueGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditActionResetExtremumValueGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditActionResetExtremumValueGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditActionResetExtremumValueGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditActionResetExtremumValueGUI)
        end
    end
end