classdef lvd_EditActionSetKinematicStateGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditActionSetKinematicStateGUI  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        text23                          matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        TimeTab                         matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        inheritTimeButtonGroup          matlab.ui.container.ButtonGroup
        inheritTimeSpecEvtCombo         matlab.ui.control.DropDown
        inheritTimeFromSpecEvtRadioBtn  matlab.ui.control.RadioButton
        inheritTimeFromPrevStateRadioBtn  matlab.ui.control.RadioButton
        SpecifyTimePanel                matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        utUbText                        matlab.ui.control.EditField
        utLbText                        matlab.ui.control.EditField
        optUtCheckbox                   matlab.ui.control.CheckBox
        utUnitLabel                     matlab.ui.control.Label
        utText                          matlab.ui.control.EditField
        text6                           matlab.ui.control.Label
        inheritTimeCheckbox             matlab.ui.control.CheckBox
        OrbitStateTab                   matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        StatePanel                      matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        SpecifyStatePanel               matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        orbit6UbText                    matlab.ui.control.EditField
        orbit5UbText                    matlab.ui.control.EditField
        orbit4UbText                    matlab.ui.control.EditField
        orbit3UbText                    matlab.ui.control.EditField
        orbit2UbText                    matlab.ui.control.EditField
        orbit1UbText                    matlab.ui.control.EditField
        orbit6LbText                    matlab.ui.control.EditField
        orbit5LbText                    matlab.ui.control.EditField
        orbit4LbText                    matlab.ui.control.EditField
        orbit3LbText                    matlab.ui.control.EditField
        orbit2LbText                    matlab.ui.control.EditField
        orbit1LbText                    matlab.ui.control.EditField
        optOrbit6Checkbox               matlab.ui.control.CheckBox
        optOrbit5Checkbox               matlab.ui.control.CheckBox
        optOrbit4Checkbox               matlab.ui.control.CheckBox
        optOrbit3Checkbox               matlab.ui.control.CheckBox
        optOrbit2Checkbox               matlab.ui.control.CheckBox
        optOrbit1Checkbox               matlab.ui.control.CheckBox
        orbit6UnitLabel                 matlab.ui.control.Label
        orbit5UnitLabel                 matlab.ui.control.Label
        orbit4UnitLabel                 matlab.ui.control.Label
        orbit3UnitLabel                 matlab.ui.control.Label
        orbit2UnitLabel                 matlab.ui.control.Label
        orbit1UnitLabel                 matlab.ui.control.Label
        orbit6Text                      matlab.ui.control.EditField
        orbit5Text                      matlab.ui.control.EditField
        orbit4Text                      matlab.ui.control.EditField
        orbit3Text                      matlab.ui.control.EditField
        orbit2Text                      matlab.ui.control.EditField
        orbit1Text                      matlab.ui.control.EditField
        orbit6Label                     matlab.ui.control.Label
        orbit5Label                     matlab.ui.control.Label
        orbit4Label                     matlab.ui.control.Label
        orbit3Label                     matlab.ui.control.Label
        orbit2Label                     matlab.ui.control.Label
        orbit1Label                     matlab.ui.control.Label
        inheritStateButtonGroup         matlab.ui.container.ButtonGroup
        inheritStateSpecEvtCombo        matlab.ui.control.DropDown
        inheritStateFromSpecEvtRadioBtn  matlab.ui.control.RadioButton
        inheritStateFromPrevStateRadioBtn  matlab.ui.control.RadioButton
        inheritStateCheckbox            matlab.ui.control.CheckBox
        OrbitalParametersPanel          matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        setFrameOptionsButton           matlab.ui.control.Button
        refFrameTypeCombo               matlab.ui.control.DropDown
        text5                           matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        elementSetCombo                 matlab.ui.control.DropDown
        text3                           matlab.ui.control.Label
        ComponentStatesTab              matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        StageStatesTab_2                matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        GridLayout18                    matlab.ui.container.GridLayout
        StageStatePanel                 matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        stageActiveCheckbox             matlab.ui.control.CheckBox
        inheritStageStateButtonGroup    matlab.ui.container.ButtonGroup
        inheritStageStateSpecEvtCombo   matlab.ui.control.DropDown
        inheritStageStateFromSpecEvtRadioBtn  matlab.ui.control.RadioButton
        inheritStageStateFromPrevStateRadioBtn  matlab.ui.control.RadioButton
        inheritStageStateCheckbox       matlab.ui.control.CheckBox
        StagesDryMassListbox            matlab.ui.control.ListBox
        EngineStatesTab                 matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        GridLayout19                    matlab.ui.container.GridLayout
        EngineStatePanel                matlab.ui.container.Panel
        GridLayout21_2                  matlab.ui.container.GridLayout
        engineActiveCheckbox            matlab.ui.control.CheckBox
        inheritEngineStateButtonGroup   matlab.ui.container.ButtonGroup
        inheritEngineStateSpecEvtCombo  matlab.ui.control.DropDown
        inheritEngineStateFromSpecEvtRadioBtn  matlab.ui.control.RadioButton
        inheritEngineStateFromPrevStateRadioBtn  matlab.ui.control.RadioButton
        inheritEngineStateCheckbox      matlab.ui.control.CheckBox
        EnginesListBox                  matlab.ui.control.ListBox
        TankMassesTab                   matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        GridLayout20                    matlab.ui.container.GridLayout
        TankMassPanel                   matlab.ui.container.Panel
        GridLayout21_3                  matlab.ui.container.GridLayout
        tankDryMassUbText               matlab.ui.control.EditField
        tankDryMassLbText               matlab.ui.control.EditField
        optTankMassCheckbox             matlab.ui.control.CheckBox
        TankMassUnitLabel               matlab.ui.control.Label
        TankMassText                    matlab.ui.control.EditField
        text6_4                         matlab.ui.control.Label
        inheritTankMassButtonGroup      matlab.ui.container.ButtonGroup
        inheritTankMassSpecEvtCombo     matlab.ui.control.DropDown
        inheritTankMassFromSpecEvtRadioBtn  matlab.ui.control.RadioButton
        inheritTankMassFromPrevStateRadioBtn  matlab.ui.control.RadioButton
        inheritTankMassCheckbox         matlab.ui.control.CheckBox
        TanksListBox                    matlab.ui.control.ListBox
        ElectricalPowerSourcesTab       matlab.ui.container.Tab
        GridLayout22                    matlab.ui.container.GridLayout
        GridLayout23                    matlab.ui.container.GridLayout
        ElectricalPowerSourceStatePanel  matlab.ui.container.Panel
        GridLayout21_4                  matlab.ui.container.GridLayout
        epsSrcActiveCheckbox            matlab.ui.control.CheckBox
        inheritEpsSrcStateButtonGroup   matlab.ui.container.ButtonGroup
        inheritEpsSrcStateSpecEvtCombo  matlab.ui.control.DropDown
        inheritEpsSrcStateFromSpecEvtRadioBtn  matlab.ui.control.RadioButton
        inheritEpsSrcStateFromPrevStateRadioBtn  matlab.ui.control.RadioButton
        inheritEpsSrcStateCheckbox      matlab.ui.control.CheckBox
        EpsSrcListbox                   matlab.ui.control.ListBox
        ElectricalPowerStorageTab       matlab.ui.container.Tab
        GridLayout24                    matlab.ui.container.GridLayout
        GridLayout25                    matlab.ui.container.GridLayout
        ElectricalPowerStorageStatePanel  matlab.ui.container.Panel
        GridLayout21_5                  matlab.ui.container.GridLayout
        GridLayout27                    matlab.ui.container.GridLayout
        storageSoCUbText                matlab.ui.control.EditField
        storageSoCLbText                matlab.ui.control.EditField
        optStorageSoCCheckbox           matlab.ui.control.CheckBox
        StorageSoCUnitLabel             matlab.ui.control.Label
        StorageSoCText                  matlab.ui.control.EditField
        text6_8                         matlab.ui.control.Label
        GridLayout26                    matlab.ui.container.GridLayout
        epsStorageActiveCheckbox        matlab.ui.control.CheckBox
        inheritEpsStorageStateButtonGroup  matlab.ui.container.ButtonGroup
        inheritEpsStorageStateSpecEvtCombo  matlab.ui.control.DropDown
        inheritEpsStorageStateFromSpecEvtRadioBtn  matlab.ui.control.RadioButton
        inheritEpsStorageStateFromPrevStateRadioBtn  matlab.ui.control.RadioButton
        inheritEpsStorageStateCheckbox  matlab.ui.control.CheckBox
        EpsStorageListbox               matlab.ui.control.ListBox
        ElectricalPowerSinksTab         matlab.ui.container.Tab
        GridLayout28                    matlab.ui.container.GridLayout
        GridLayout29                    matlab.ui.container.GridLayout
        ElectricalPowerSinkStatePanel   matlab.ui.container.Panel
        GridLayout21_6                  matlab.ui.container.GridLayout
        epsSinkActiveCheckbox           matlab.ui.control.CheckBox
        inheritEpsSinkStateButtonGroup  matlab.ui.container.ButtonGroup
        inheritEpsSinkStateSpecEvtCombo  matlab.ui.control.DropDown
        inheritEpsSinkStateFromSpecEvtRadioBtn  matlab.ui.control.RadioButton
        inheritEpsSinkStateFromPrevStateRadioBtn  matlab.ui.control.RadioButton
        inheritEpsSinkStateCheckbox     matlab.ui.control.CheckBox
        EpsSinkListbox                  matlab.ui.control.ListBox
        OtherStateElementsTab           matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        text25                          matlab.ui.control.Label
        inheritOtherStateElementsButtonGroup  matlab.ui.container.ButtonGroup
        inheritOtherStateElementsEvtCombo  matlab.ui.control.DropDown
        inheritOtherStateElementsFromSpecEvtRadioBtn  matlab.ui.control.RadioButton
        inheritOtherStateElementsFromPrevStateRadioBtn  matlab.ui.control.RadioButton
        GridLayout13                    matlab.ui.container.GridLayout
        text24                          matlab.ui.control.Label
        inheritOtherStateElementsCheckbox  matlab.ui.control.CheckBox
        GridLayout2                     matlab.ui.container.GridLayout
        cancelButton                    matlab.ui.control.Button
        saveAndCloseButton              matlab.ui.control.Button
        text2                           matlab.ui.control.Label
        epochContextMenu                matlab.ui.container.ContextMenu
        enterUTAsDateTimeContextMenu    matlab.ui.container.Menu
        orbitPanelContextMenu           matlab.ui.container.ContextMenu
        getOrbitFromSFSFileContextMenu  matlab.ui.container.Menu
        getOrbitFromKSPTOTConnectContextMenu  matlab.ui.container.Menu
        getOrbitFromKSPActiveVesselMenu  matlab.ui.container.Menu
        copyOrbitToClipboardMenu        matlab.ui.container.Menu
        pasteOrbitFromClipboardMenu     matlab.ui.container.Menu
    end


    methods (Access = private)
        function [orbitModel, elemSetEnum] = getCurrentElemSetFromExistingValues(app, handles)
            contents = cellstr(get(handles.elementSetCombo,'String'));
            selElemSet = contents{get(handles.elementSetCombo,'Value')};
            elemSetEnum = ElementSetEnum.getEnumForListboxStr(selElemSet);

            time = str2double(handles.utText.String);

            orbit1Elem = str2double(handles.orbit1Text.String);
            orbit2Elem = str2double(handles.orbit2Text.String);
            orbit3Elem = str2double(handles.orbit3Text.String);
            orbit4Elem = str2double(handles.orbit4Text.String);
            orbit5Elem = str2double(handles.orbit5Text.String);
            orbit6Elem = str2double(handles.orbit6Text.String);

            curElemSet = getappdata(handles.lvd_EditActionSetKinematicStateGUI,'curElemSet');
            frame = curElemSet.frame;

            switch elemSetEnum
                case ElementSetEnum.CartesianElements
                    orbitModel = CartesianElementSet(time, [orbit1Elem; orbit2Elem; orbit3Elem], [orbit4Elem; orbit5Elem; orbit6Elem], frame);

                case ElementSetEnum.KeplerianElements
                    orbitModel = KeplerianElementSet(time, orbit1Elem, orbit2Elem, deg2rad(orbit3Elem), deg2rad(orbit4Elem), deg2rad(orbit5Elem), deg2rad(orbit6Elem), frame);

                case ElementSetEnum.GeographicElements
                    orbitModel = GeographicElementSet(time, deg2rad(orbit1Elem), deg2rad(orbit2Elem), orbit3Elem, deg2rad(orbit4Elem), deg2rad(orbit5Elem), orbit6Elem, frame);

                case ElementSetEnum.UniversalElements
                    orbitModel = UniversalElementSet(time, orbit1Elem, orbit2Elem, deg2rad(orbit3Elem), deg2rad(orbit4Elem), deg2rad(orbit5Elem), orbit6Elem, frame);

                otherwise
                    error('Unknown element set type: %s', class(elemSetEnum));
            end
        end

        function bodyInfo = getSelectedBodyInfo(app, handles)
            % 	lvdData = getappdata(handles.lvd_EditActionSetKinematicStateGUI,'lvdData');
            %     celBodyData = lvdData.celBodyData;

            curElemSet = getappdata(handles.lvd_EditActionSetKinematicStateGUI,'curElemSet');
            bodyInfo = curElemSet.frame.getOriginBody();
        end

        function populateGUI(app, handles, action, lvdData)
            orbitModel = action.orbitModel;

            if(isnan(orbitModel.time))
                orbitModel.time = 0;
            end

            if(any(isnan(orbitModel.getElementVector())))
                bodyInfo = lvdData.getDefaultInitialBodyInfo(lvdData.celBodyData);
                newFrame = bodyInfo.getBodyFixedFrame();

                orbitModel = GeographicElementSet(orbitModel.time, 0, 0, 0, 0, 0, 0, newFrame);
            end

            if(isempty(orbitModel.frame))
                bodyInfo = lvdData.getDefaultInitialBodyInfo(lvdData.celBodyData);
                newFrame = bodyInfo.getBodyCenteredInertialFrame();

                orbitModel.frame = newFrame;
            end

            %     frame = orbitModel.frame;
            setappdata(handles.lvd_EditActionSetKinematicStateGUI,'curElemSet',orbitModel);

            %     bodyInfo = frame.getOriginBody();
            %     populateBodiesCombo(lvdData.celBodyData, handles.centralBodyCombo, false);
            %     value = findValueFromComboBox(bodyInfo.name, handles.centralBodyCombo);
            % 	handles.centralBodyCombo.Value = value;

            handles.refFrameTypeCombo.String = ReferenceFrameEnum.getListBoxStr();
            handles.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(orbitModel.frame.typeEnum.name);
            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', orbitModel.frame.getNameStr());

            handles.elementSetCombo.String = ElementSetEnum.getListBoxStr();
            [value, elemSetEnum] = ElementSetEnum.getIndForName(orbitModel.typeEnum.name);
            handles.elementSetCombo.Value = value;
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            elementSetCombo_Callback(app, evt);

            [evtListStr,events] = lvdData.script.getListboxStr();
            thisActionEvtNum = action.event.getEventNum();
            if(not(isempty(thisActionEvtNum)))
                if(thisActionEvtNum > 1)
                    finalEvtNumToShow = thisActionEvtNum-1;
                else
                    finalEvtNumToShow = thisActionEvtNum;
                end
                evtListStr = evtListStr(1:finalEvtNumToShow);
            end

            %inherit time
            switch action.inheritTimeFrom
                case InheritStateEnum.InheritFromLastState
                    handles.inheritTimeFromPrevStateRadioBtn.Value = 1;

                case InheritStateEnum.InheritFromSpecifiedEvent
                    handles.inheritTimeFromSpecEvtRadioBtn.Value = 1;

                otherwise
                    error('Unknown inheritance type (time): %s', class(action.inheritTimeFrom));
            end

            handles.inheritTimeSpecEvtCombo.String = evtListStr;
            if(not(isempty(action.inheritTimeFromEvent)))
                evtNum = action.inheritTimeFromEvent.getEventNum();
                if(evtNum > length(evtListStr))
                    handles.inheritTimeSpecEvtCombo.Value = 1;
                else
                    handles.inheritTimeSpecEvtCombo.Value = evtNum;
                end
            end

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            inheritTimeButtonGroup_SelectionChangedFcn(app, evt);

            %inherit pos/vel state
            switch action.inheritPosVelFrom
                case InheritStateEnum.InheritFromLastState
                    handles.inheritStateFromPrevStateRadioBtn.Value = 1;

                case InheritStateEnum.InheritFromSpecifiedEvent
                    handles.inheritStateFromSpecEvtRadioBtn.Value = 1;

                otherwise
                    error('Unknown inheritance type (state): %s', class(action.inheritPosVelFrom));
            end

            handles.inheritStateSpecEvtCombo.String = evtListStr;
            if(not(isempty(action.inheritPosVelFromEvent)))
                evtNum = action.inheritPosVelFromEvent.getEventNum();
                if(evtNum > length(evtListStr))
                    handles.inheritStateSpecEvtCombo.Value = 1;
                else
                    handles.inheritStateSpecEvtCombo.Value = evtNum;
                end
            end

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            inheritStateButtonGroup_SelectionChangedFcn(app, evt);

            %inherit stage states
%             switch action.inheritStageStatesFrom
%                 case InheritStateEnum.InheritFromLastState
%                     handles.inheritStageStatesFromPrevStateRadioBtn.Value = 1;
% 
%                 case InheritStateEnum.InheritFromSpecifiedEvent
%                     handles.inheritStageStatesFromSpecEvtRadioBtn.Value = 1;
% 
%                 otherwise
%                     error('Unknown inheritance type (stage states): %s', class(action.inheritStageStatesFrom));
%             end
% 
%             handles.inheritStageStatesSpecEvtCombo.String = evtListStr;
%             if(not(isempty(action.inheritStageStatesFromEvent)))
%                 evtNum = action.inheritStageStatesFromEvent.getEventNum();
%                 if(evtNum > length(evtListStr))
%                     handles.inheritStageStatesSpecEvtCombo.Value = 1;
%                 else
%                     handles.inheritStageStatesSpecEvtCombo.Value = evtNum;
%                 end
%             end
% 
%             evt = matlab.ui.eventdata.ValueChangedData(0, 0);
%             inheritStageStatesButtonGroup_SelectionChangedFcn(app, evt);

            %Inherit stage states (2)
            stageStates = action.stageStates;
            app.StagesDryMassListbox.ItemsData = stageStates;
            app.StagesDryMassListbox.Items = getListboxStr(stageStates);
            app.inheritStageStateSpecEvtCombo.ItemsData = events;
            app.inheritStageStateSpecEvtCombo.Items = evtListStr;
            app.populateStageStatesTab();

            %Inherit engine states
            engineStates = action.engineStates;
            app.EnginesListBox.ItemsData = engineStates;
            app.EnginesListBox.Items = getListboxStr(engineStates);
            app.inheritEngineStateSpecEvtCombo.ItemsData = events;
            app.inheritEngineStateSpecEvtCombo.Items = evtListStr;
            app.populateEngineStatesTab();

            %Inherit tank state
            tankStates = action.tankStates;
            app.TanksListBox.ItemsData = tankStates;
            app.TanksListBox.Items = getListboxStr(tankStates);
            app.inheritTankMassSpecEvtCombo.ItemsData = events;
            app.inheritTankMassSpecEvtCombo.Items = evtListStr;
            app.populateTankStatesTab();

            %Inherit Eps Src state
            epsSrcStates = action.epsSrcStates;
            app.EpsSrcListbox.ItemsData = epsSrcStates;
            app.EpsSrcListbox.Items = getListboxStr(epsSrcStates);
            app.inheritEpsSrcStateSpecEvtCombo.ItemsData = events;
            app.inheritEpsSrcStateSpecEvtCombo.Items = evtListStr;
            app.populateEpsSrcStatesTab();

            %Inherit Eps Storage state
            epsStorageStates = action.epsStorageStates;
            app.EpsStorageListbox.ItemsData = epsStorageStates;
            app.EpsStorageListbox.Items = getListboxStr(epsStorageStates);
            app.inheritEpsStorageStateSpecEvtCombo.ItemsData = events;
            app.inheritEpsStorageStateSpecEvtCombo.Items = evtListStr;
            app.populateEpsStorageStatesTab();

            %Inherit Eps Sink state
            epsSinkStates = action.epsSinkStates;
            app.EpsSinkListbox.ItemsData = epsSinkStates;
            app.EpsSinkListbox.Items = getListboxStr(epsSinkStates);
            app.inheritEpsSinkStateSpecEvtCombo.ItemsData = events;
            app.inheritEpsSinkStateSpecEvtCombo.Items = evtListStr;
            app.populateEpsSinkStatesTab();

            %inherit other state elements
            switch action.inheritStateElemsFrom
                case InheritStateEnum.InheritFromLastState
                    handles.inheritOtherStateElementsFromPrevStateRadioBtn.Value = 1;

                case InheritStateEnum.InheritFromSpecifiedEvent
                    handles.inheritOtherStateElementsFromSpecEvtRadioBtn.Value = 1;

                otherwise
                    error('Unknown inheritance type (other state elements): %s', class(action.inheritStateElemsFrom));
            end

            handles.inheritOtherStateElementsEvtCombo.String = evtListStr;
            if(not(isempty(action.inheritStateElemsFromEvent)))
                evtNum = action.inheritStateElemsFromEvent.getEventNum();
                if(evtNum > length(evtListStr))
                    handles.inheritOtherStateElementsEvtCombo.Value = 1;
                else
                    handles.inheritOtherStateElementsEvtCombo.Value = evtNum;
                end
            end

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            inheritOtherStateElementsButtonGroup_SelectionChangedFcn(app, evt);

            %populate pos/vel states stuff
            if(isempty(orbitModel.time))
                orbitModel.time = 0;
            end
            handles.utText.String = fullAccNum2Str(orbitModel.time);

            elemVector = orbitModel.getElementVector();
            handles.orbit1Text.String = fullAccNum2Str(elemVector(1));
            handles.orbit2Text.String = fullAccNum2Str(elemVector(2));
            handles.orbit3Text.String = fullAccNum2Str(elemVector(3));
            handles.orbit4Text.String = fullAccNum2Str(elemVector(4));
            handles.orbit5Text.String = fullAccNum2Str(elemVector(5));
            handles.orbit6Text.String = fullAccNum2Str(elemVector(6));

            handles.inheritTimeCheckbox.Value = double(action.inheritTime);
            handles.inheritStateCheckbox.Value = double(action.inheritPosVel);

            optVar = action.optVar;
            if(isempty(optVar))
                optVar = SetKinematicStateActionVariable(action);
            end
            useTf = optVar.getUseTfForVariable();

            handles.optUtCheckbox.Value = double(useTf(1));
            handles.optOrbit1Checkbox.Value = double(useTf(2));
            handles.optOrbit2Checkbox.Value = double(useTf(3));
            handles.optOrbit3Checkbox.Value = double(useTf(4));
            handles.optOrbit4Checkbox.Value = double(useTf(5));
            handles.optOrbit5Checkbox.Value = double(useTf(6));
            handles.optOrbit6Checkbox.Value = double(useTf(7));

            optVar.setUseTfForVariable(true(size(useTf)));
            [lb, ub] = optVar.getBndsForVariable();
            optVar.setUseTfForVariable(useTf);

            switch elemSetEnum
                case ElementSetEnum.CartesianElements
                    %nothing needed

                case ElementSetEnum.KeplerianElements
                    lb = [lb(1) lb(2) lb(3) rad2deg(lb(4)) rad2deg(lb(5)) rad2deg(lb(6)) rad2deg(lb(7))];
                    ub = [ub(1) ub(2) ub(3) rad2deg(ub(4)) rad2deg(ub(5)) rad2deg(ub(6)) rad2deg(ub(7))];

                case ElementSetEnum.GeographicElements
                    lb = [lb(1) rad2deg(lb(2)) rad2deg(lb(3)) lb(4) rad2deg(lb(5)) rad2deg(lb(6)) lb(7)];
                    ub = [lb(1) rad2deg(ub(2)) rad2deg(ub(3)) ub(4) rad2deg(ub(5)) rad2deg(ub(6)) ub(7)];

                case ElementSetEnum.UniversalElements
                    lb = [lb(1) lb(2) lb(3) rad2deg(lb(4)) rad2deg(lb(5)) rad2deg(lb(6)) lb(7)];
                    ub = [ub(1) ub(2) ub(3) rad2deg(ub(4)) rad2deg(ub(5)) rad2deg(ub(6)) ub(7)];

                otherwise
                    error('Unknown element set type: %s', class(elemSetEnum));
            end

            handles.utLbText.String     = fullAccNum2Str(lb(1));
            handles.orbit1LbText.String = fullAccNum2Str(lb(2));
            handles.orbit2LbText.String = fullAccNum2Str(lb(3));
            handles.orbit3LbText.String = fullAccNum2Str(lb(4));
            handles.orbit4LbText.String = fullAccNum2Str(lb(5));
            handles.orbit5LbText.String = fullAccNum2Str(lb(6));
            handles.orbit6LbText.String = fullAccNum2Str(lb(7));

            handles.utUbText.String     = fullAccNum2Str(ub(1));
            handles.orbit1UbText.String = fullAccNum2Str(ub(2));
            handles.orbit2UbText.String = fullAccNum2Str(ub(3));
            handles.orbit3UbText.String = fullAccNum2Str(ub(4));
            handles.orbit4UbText.String = fullAccNum2Str(ub(5));
            handles.orbit5UbText.String = fullAccNum2Str(ub(6));
            handles.orbit6UbText.String = fullAccNum2Str(ub(7));

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            inheritTimeCheckbox_Callback(app, evt);
            inheritStateCheckbox_Callback(app, evt);
            optUtCheckbox_Callback(app, evt);
            optOrbit1Checkbox_Callback(app, evt);
            optOrbit2Checkbox_Callback(app, evt);
            optOrbit3Checkbox_Callback(app, evt);
            optOrbit4Checkbox_Callback(app, evt);
            optOrbit5Checkbox_Callback(app, evt);
            optOrbit6Checkbox_Callback(app, evt);
        end

        function updateStateDueToFrameChange(app, handles, newFrame)
            lvdData = getappdata(handles.lvd_EditActionSetKinematicStateGUI,'lvdData');
            celBodyData = lvdData.celBodyData;

            curElemSet = getappdata(handles.lvd_EditActionSetKinematicStateGUI,'curElemSet');

            contents = cellstr(get(handles.refFrameTypeCombo,'String'));
            selFrameType = contents{get(handles.refFrameTypeCombo,'Value')};
            refFrameEnum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);

            if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
                refFrameEnum = newFrame.typeEnum;
                handles.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
            end

            switch refFrameEnum
                case ReferenceFrameEnum.BodyCenteredInertial
                    if(isempty(newFrame))
                        bodyInfo = getSelectedBodyInfo(app, handles);
                    else
                        bodyInfo = newFrame.getOriginBody();
                    end

                    newFrame = bodyInfo.getBodyCenteredInertialFrame();

                case ReferenceFrameEnum.BodyFixedRotating
                    if(isempty(newFrame))
                        bodyInfo = getSelectedBodyInfo(app, handles);
                    else
                        bodyInfo = newFrame.getOriginBody();
                    end

                    newFrame = bodyInfo.getBodyFixedFrame();

                case ReferenceFrameEnum.TwoBodyRotating
                    if(isempty(newFrame))
                        bodyInfo = getSelectedBodyInfo(app, handles);
                        if(not(isempty(bodyInfo.childrenBodyInfo)))
                            primaryBody = bodyInfo;
                            secondaryBody = bodyInfo.childrenBodyInfo(1);
                        else
                            primaryBody = bodyInfo.getParBodyInfo();
                            secondaryBody = bodyInfo;
                        end

                        originPt = TwoBodyRotatingFrameOriginEnum.Primary;

                        newFrame = TwoBodyRotatingFrame(primaryBody, secondaryBody, originPt, celBodyData);
                    else
                        newFrame = TwoBodyRotatingFrame(newFrame.primaryBodyInfo, newFrame.secondaryBodyInfo, newFrame.originPt, celBodyData);
                    end

                case ReferenceFrameEnum.UserDefined
                    if(isempty(newFrame))
                        numFrames = lvdData.geometry.refFrames.getNumRefFrames();
                        if(numFrames >= 1)
                            geometricFrame = AbstractGeometricRefFrame.empty(1,0);
                            for(i=1:numFrames)
                                frame = lvdData.geometry.refFrames.getRefFrameAtInd(i);
                                if(frame.isVehDependent() == false)
                                    geometricFrame = frame;
                                    break;
                                end
                            end

                            if(not(isempty(geometricFrame)))
                                newFrame = UserDefinedGeometricFrame(geometricFrame, lvdData);
                            else
                                bodyInfo = getSelectedBodyInfo(app, handles);
                                newFrame = bodyInfo.getBodyCenteredInertialFrame();

                                warndlg('There are no geometric frames available which are not dependent on vehicle properties.  A body-centered inertial frame will be selected instead.');
                            end
                        else
                            bodyInfo = getSelectedBodyInfo(app, handles);
                            newFrame = bodyInfo.getBodyCenteredInertialFrame();

                            warndlg('There are no geometric frames available which are not dependent on vehicle properties.  A body-centered inertial frame will be selected instead.');
                        end

                    else
                        newFrame = UserDefinedGeometricFrame(newFrame.geometricFrame, lvdData);
                    end

                otherwise
                    error('Unknown reference frame type: %s', string(refFrameEnum));
            end

            if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
                refFrameEnum = newFrame.typeEnum;
                handles.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
            end

            try
                newElemSet = curElemSet.convertToFrame(newFrame);

                elemVect = newElemSet.getElementVector();

                if(any(isnan(elemVect)) || any(not(isfinite(elemVect))))
                    error('State conversion resulted in NaN');
                end

                handles.orbit1Text.String = fullAccNum2Str(elemVect(1));
                handles.orbit2Text.String = fullAccNum2Str(elemVect(2));
                handles.orbit3Text.String = fullAccNum2Str(elemVect(3));
                handles.orbit4Text.String = fullAccNum2Str(elemVect(4));
                handles.orbit5Text.String = fullAccNum2Str(elemVect(5));
                handles.orbit6Text.String = fullAccNum2Str(elemVect(6));

                setappdata(handles.lvd_EditActionSetKinematicStateGUI,'curElemSet', newElemSet);
            catch ME %#ok<NASGU>
                updateValuesInState(app, handles);
                uialert(app.lvd_EditActionSetKinematicStateGUI, 'Error converting state to new frame.', 'Conversion Error', "Icon","error")
            end

            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', newFrame.getNameStr());
        end

        function updateValuesInState(app, handles)
            curElemSet = getappdata(handles.lvd_EditActionSetKinematicStateGUI,'curElemSet');
            newElemSet = curElemSet;

            time = str2double(handles.utText.String);
            orbit1Elem = str2double(handles.orbit1Text.String);
            orbit2Elem = str2double(handles.orbit2Text.String);
            orbit3Elem = str2double(handles.orbit3Text.String);
            orbit4Elem = str2double(handles.orbit4Text.String);
            orbit5Elem = str2double(handles.orbit5Text.String);
            orbit6Elem = str2double(handles.orbit6Text.String);

            frame = newElemSet.frame;
            switch newElemSet.typeEnum
                case ElementSetEnum.CartesianElements
                    newElemSet = CartesianElementSet(time, [orbit1Elem; orbit2Elem; orbit3Elem], [orbit4Elem; orbit5Elem; orbit6Elem], frame);

                case ElementSetEnum.KeplerianElements
                    newElemSet = KeplerianElementSet(time, orbit1Elem, orbit2Elem, deg2rad(orbit3Elem), deg2rad(orbit4Elem), deg2rad(orbit5Elem), deg2rad(orbit6Elem), frame);

                case ElementSetEnum.GeographicElements
                    newElemSet = GeographicElementSet(time, deg2rad(orbit1Elem), deg2rad(orbit2Elem), orbit3Elem, deg2rad(orbit4Elem), deg2rad(orbit5Elem), orbit6Elem, frame);

                case ElementSetEnum.UniversalElements
                    newElemSet = UniversalElementSet(time, orbit1Elem, orbit2Elem, deg2rad(orbit3Elem), deg2rad(orbit4Elem), deg2rad(orbit5Elem), orbit6Elem, frame);

                otherwise
                    error('Unknown element set type: %s', class(elemSetEnum));
            end

            setappdata(handles.lvd_EditActionSetKinematicStateGUI,'curElemSet', newElemSet);
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};
            bodyInfo = getSelectedBodyInfo(app, handles);

            if(handles.inheritTimeCheckbox.Value == 0)
                ut = str2double(get(handles.utText,'String'));
                enteredStr = get(handles.utText,'String');
                numberName = 'Epoch (UT)';
                lb = 0;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(ut, numberName, lb, ub, isInt, errMsg, enteredStr);
            end

            if(handles.inheritTimeCheckbox.Value == 0 && handles.optUtCheckbox.Value)
                utLb = str2double(get(handles.utLbText,'String'));
                enteredStr = get(handles.utLbText,'String');
                numberName = 'Epoch (UT) (Lower Bound)';
                lb = 0;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(utLb, numberName, lb, ub, isInt, errMsg, enteredStr);

                utUb = str2double(get(handles.utUbText,'String'));
                enteredStr = get(handles.utUbText,'String');
                numberName = 'Epoch (UT) (Upper Bound)';
                lb = 0;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(utUb, numberName, lb, ub, isInt, errMsg, enteredStr);

                if(utLb <= utUb)
                    ut = str2double(get(handles.utText,'String'));
                    enteredStr = get(handles.utText,'String');
                    numberName = 'Epoch (UT)';
                    isInt = false;
                    errMsg = validateNumber(ut, numberName, utLb, utUb, isInt, errMsg, enteredStr);
                else
                    errMsg{end+1} = sprintf('%s lower bound must be less than the upper bound.', 'Epoch (UT)');
                end
            end

            if(handles.inheritStateCheckbox.Value == 0)
                contents = cellstr(get(handles.elementSetCombo,'String'));
                selElemSet = contents{get(handles.elementSetCombo,'Value')};
                elemSetEnum = ElementSetEnum.getEnumForListboxStr(selElemSet);

                checkElementValues = ones(1,6);
                checkElementBnds = [handles.optOrbit1Checkbox.Value, ...
                    handles.optOrbit2Checkbox.Value, ...
                    handles.optOrbit3Checkbox.Value, ...
                    handles.optOrbit4Checkbox.Value, ...
                    handles.optOrbit5Checkbox.Value, ...
                    handles.optOrbit6Checkbox.Value];

                switch elemSetEnum
                    case ElementSetEnum.CartesianElements
                        errMsg = CartesianElementSet.validateInputOrbit(errMsg, handles.orbit1Text, handles.orbit2Text, handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, handles.orbit6Text, bodyInfo, '', checkElementValues);
                        errMsg = CartesianElementSet.validateInputOrbit(errMsg, handles.orbit1LbText, handles.orbit2LbText, handles.orbit3LbText, handles.orbit4LbText, handles.orbit5LbText, handles.orbit6LbText, bodyInfo, 'Lower', checkElementBnds);
                        errMsg = CartesianElementSet.validateInputOrbit(errMsg, handles.orbit1UbText, handles.orbit2UbText, handles.orbit3UbText, handles.orbit4UbText, handles.orbit5UbText, handles.orbit6UbText, bodyInfo, 'Upper', checkElementBnds);

                    case ElementSetEnum.KeplerianElements
                        errMsg = KeplerianElementSet.validateInputOrbit(errMsg, handles.orbit1Text, handles.orbit2Text, handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, handles.orbit6Text, bodyInfo, '', checkElementValues);
                        errMsg = KeplerianElementSet.validateInputOrbit(errMsg, handles.orbit1LbText, handles.orbit2LbText, handles.orbit3LbText, handles.orbit4LbText, handles.orbit5LbText, handles.orbit6LbText, bodyInfo, 'Lower', checkElementBnds);
                        errMsg = KeplerianElementSet.validateInputOrbit(errMsg, handles.orbit1UbText, handles.orbit2UbText, handles.orbit3UbText, handles.orbit4UbText, handles.orbit5UbText, handles.orbit6UbText, bodyInfo, 'Upper', checkElementBnds);

                    case ElementSetEnum.GeographicElements
                        errMsg = GeographicElementSet.validateInputOrbit(errMsg, handles.orbit1Text, handles.orbit2Text, handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, handles.orbit6Text, bodyInfo, '', checkElementValues);
                        errMsg = GeographicElementSet.validateInputOrbit(errMsg, handles.orbit1LbText, handles.orbit2LbText, handles.orbit3LbText, handles.orbit4LbText, handles.orbit5LbText, handles.orbit6LbText, bodyInfo, 'Lower', checkElementBnds);
                        errMsg = GeographicElementSet.validateInputOrbit(errMsg, handles.orbit1UbText, handles.orbit2UbText, handles.orbit3UbText, handles.orbit4UbText, handles.orbit5UbText, handles.orbit6UbText, bodyInfo, 'Upper', checkElementBnds);

                    case ElementSetEnum.UniversalElements
                        errMsg = UniversalElementSet.validateInputOrbit(errMsg, handles.orbit1Text, handles.orbit2Text, handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, handles.orbit6Text, bodyInfo, '', checkElementValues);
                        errMsg = UniversalElementSet.validateInputOrbit(errMsg, handles.orbit1LbText, handles.orbit2LbText, handles.orbit3LbText, handles.orbit4LbText, handles.orbit5LbText, handles.orbit6LbText, bodyInfo, 'Lower', checkElementBnds);
                        errMsg = UniversalElementSet.validateInputOrbit(errMsg, handles.orbit1UbText, handles.orbit2UbText, handles.orbit3UbText, handles.orbit4UbText, handles.orbit5UbText, handles.orbit6UbText, bodyInfo, 'Upper', checkElementBnds);

                    otherwise
                        error('Unknown element set type: %s', class(elemSetEnum));
                end

                hVal = [handles.orbit1Text, handles.orbit2Text, handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, handles.orbit6Text];
                hLb = [handles.orbit1LbText, handles.orbit2LbText, handles.orbit3LbText, handles.orbit4LbText, handles.orbit5LbText, handles.orbit6LbText];
                hUb = [handles.orbit1UbText, handles.orbit2UbText, handles.orbit3UbText, handles.orbit4UbText, handles.orbit5UbText, handles.orbit6UbText];

                elemNames = elemSetEnum.elemNames;

                for(i=1:length(hVal))
                    if(checkElementBnds(i) == 1)
                        lb = str2double(get(hLb(i),'String'));
                        ub = str2double(get(hUb(i),'String'));

                        if(lb <= ub)
                            value = str2double(get(hVal(i),'String'));
                            enteredStr = get(hVal(i),'String');
                            numberName = [elemNames{i}];
                            isInt = false;
                            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);
                        else
                            errMsg{end+1} = sprintf('%s lower bound must be less than the upper bound.', elemNames{i}); %#ok<AGROW>
                        end
                    end
                end
            end
        end

        function saveActionAndClose(app)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            [~, ~, handles] = convertToGUIDECallbackArguments(app, evt);
            hObject = handles.lvd_EditActionSetKinematicStateGUI;

            lvdData = getappdata(handles.lvd_EditActionSetKinematicStateGUI,'lvdData');
            action = getappdata(handles.lvd_EditActionSetKinematicStateGUI, 'action');

            action.stateLog = lvdData.stateLog;

            contents = cellstr(get(handles.elementSetCombo,'String'));
            selElemSet = contents{get(handles.elementSetCombo,'Value')};
            elemSetEnum = ElementSetEnum.getEnumForListboxStr(selElemSet);

            time = str2double(handles.utText.String);

            orbit1Elem = str2double(handles.orbit1Text.String);
            orbit2Elem = str2double(handles.orbit2Text.String);
            orbit3Elem = str2double(handles.orbit3Text.String);
            orbit4Elem = str2double(handles.orbit4Text.String);
            orbit5Elem = str2double(handles.orbit5Text.String);
            orbit6Elem = str2double(handles.orbit6Text.String);

            if(isempty(orbit1Elem))
                orbit1Elem = 0.1;
            end

            if(isempty(orbit2Elem))
                orbit2Elem = 0.1;
            end

            if(isempty(orbit3Elem))
                orbit3Elem = 0.1;
            end

            if(isempty(orbit4Elem))
                orbit4Elem = 0.1;
            end

            if(isempty(orbit5Elem))
                orbit5Elem = 0.1;
            end

            if(isempty(orbit6Elem))
                orbit6Elem = 0.1;
            end

            curElemSet = getappdata(handles.lvd_EditActionSetKinematicStateGUI,'curElemSet');
            frame = curElemSet.frame;

            switch elemSetEnum
                case ElementSetEnum.CartesianElements
                    orbitModel = CartesianElementSet(time, [orbit1Elem; orbit2Elem; orbit3Elem], [orbit4Elem; orbit5Elem; orbit6Elem], frame);

                case ElementSetEnum.KeplerianElements
                    orbitModel = KeplerianElementSet(time, orbit1Elem, orbit2Elem, deg2rad(orbit3Elem), deg2rad(orbit4Elem), deg2rad(orbit5Elem), deg2rad(orbit6Elem), frame);

                case ElementSetEnum.GeographicElements
                    orbitModel = GeographicElementSet(time, deg2rad(orbit1Elem), deg2rad(orbit2Elem), orbit3Elem, deg2rad(orbit4Elem), deg2rad(orbit5Elem), orbit6Elem, frame);

                case ElementSetEnum.UniversalElements
                    orbitModel = UniversalElementSet(time, orbit1Elem, orbit2Elem, deg2rad(orbit3Elem), deg2rad(orbit4Elem), deg2rad(orbit5Elem), orbit6Elem, frame);

                otherwise
                    error('Unknown element set type: %s', class(elemSetEnum));
            end

            action.orbitModel = orbitModel;

            %inherit time
            action.inheritTime = logical(handles.inheritTimeCheckbox.Value);
            switch handles.inheritTimeButtonGroup.SelectedObject
                case handles.inheritTimeFromPrevStateRadioBtn
                    action.inheritTimeFrom = InheritStateEnum.InheritFromLastState;
                    action.inheritTimeFromEvent = LaunchVehicleEvent.empty(1,0);

                case handles.inheritTimeFromSpecEvtRadioBtn
                    action.inheritTimeFrom = InheritStateEnum.InheritFromSpecifiedEvent;
                    action.inheritTimeFromEvent = lvdData.script.getEventForInd(handles.inheritTimeSpecEvtCombo.Value);

                otherwise
                    error('Unknown UI widget handle for time inheritence selection: %s', handles.inheritTimeButtonGroup.SelectedObject.Tag);
            end

            %inherit pos/vel state
            action.inheritPosVel = logical(handles.inheritStateCheckbox.Value);
            switch handles.inheritStateButtonGroup.SelectedObject
                case handles.inheritStateFromPrevStateRadioBtn
                    action.inheritPosVelFrom = InheritStateEnum.InheritFromLastState;
                    action.inheritPosVelFromEvent = LaunchVehicleEvent.empty(1,0);

                case handles.inheritStateFromSpecEvtRadioBtn
                    action.inheritPosVelFrom = InheritStateEnum.InheritFromSpecifiedEvent;
                    action.inheritPosVelFromEvent = lvdData.script.getEventForInd(handles.inheritStateSpecEvtCombo.Value);

                otherwise
                    error('Unknown UI widget handle for pos/vel state inheritence selection: %s', handles.inheritStateButtonGroup.SelectedObject.Tag);
            end

            %inherit stage states
            action.generateLvComponentElements();
            %             action.inheritStageStates = logical(handles.inheritStageStatesCheckbox.Value);
            %             switch handles.inheritStageStatesButtonGroup.SelectedObject
            %                 case handles.inheritStageStatesFromPrevStateRadioBtn
            %                     action.inheritStageStatesFrom = InheritStateEnum.InheritFromLastState;
            %                     action.inheritStageStatesFromEvent = LaunchVehicleEvent.empty(1,0);
            %
            %                 case handles.inheritStageStatesFromSpecEvtRadioBtn
            %                     action.inheritStageStatesFrom = InheritStateEnum.InheritFromSpecifiedEvent;
            %                     action.inheritStageStatesFromEvent = lvdData.script.getEventForInd(handles.inheritStageStatesSpecEvtCombo.Value);
            %
            %                 otherwise
            %                     error('Unknown UI widget handle for stage state inheritence selection: %s', handles.inheritStageStatesButtonGroup.SelectedObject.Tag);
            %             end

            %inherit other state elements
            action.inheritStateElems = logical(handles.inheritOtherStateElementsCheckbox.Value);
            switch handles.inheritOtherStateElementsButtonGroup.SelectedObject
                case handles.inheritOtherStateElementsFromPrevStateRadioBtn
                    action.inheritStateElemsFrom = InheritStateEnum.InheritFromLastState;
                    action.inheritStateElemsFromEvent = LaunchVehicleEvent.empty(1,0);

                case handles.inheritOtherStateElementsFromSpecEvtRadioBtn
                    action.inheritStateElemsFrom = InheritStateEnum.InheritFromSpecifiedEvent;
                    action.inheritStateElemsFromEvent = lvdData.script.getEventForInd(handles.inheritOtherStateElementsEvtCombo.Value);

                otherwise
                    error('Unknown UI widget handle for other state element inheritence selection: %s', handles.inheritOtherStateElementsButtonGroup.SelectedObject.Tag);
            end

            %Vars
            optVar = action.optVar;
            if(not(isempty(optVar)))
                lvdData.optimizer.vars.removeVariable(optVar);
            end
            optVar = SetKinematicStateActionVariable(action);

            optUt         = logical(handles.optUtCheckbox.Value) && not(action.inheritTime);
            optOrbit1Elem = logical(handles.optOrbit1Checkbox.Value) && not(action.inheritPosVel);
            optOrbit2Elem = logical(handles.optOrbit2Checkbox.Value) && not(action.inheritPosVel);
            optOrbit3Elem = logical(handles.optOrbit3Checkbox.Value) && not(action.inheritPosVel);
            optOrbit4Elem = logical(handles.optOrbit4Checkbox.Value) && not(action.inheritPosVel);
            optOrbit5Elem = logical(handles.optOrbit5Checkbox.Value) && not(action.inheritPosVel);
            optOrbit6Elem = logical(handles.optOrbit6Checkbox.Value) && not(action.inheritPosVel);

            allUseTf = optVar.getUseTfForVariable();
            useTf = [optUt optOrbit1Elem optOrbit2Elem optOrbit3Elem optOrbit4Elem optOrbit5Elem optOrbit6Elem allUseTf(8:end)];
            optVar.setUseTfForVariable(useTf);
            lvdData.optimizer.vars.addVariable(optVar);

            utLb = str2double(handles.utLbText.String);
            orbit1ElemLb = str2double(handles.orbit1LbText.String);
            orbit2ElemLb = str2double(handles.orbit2LbText.String);
            orbit3ElemLb = str2double(handles.orbit3LbText.String);
            orbit4ElemLb = str2double(handles.orbit4LbText.String);
            orbit5ElemLb = str2double(handles.orbit5LbText.String);
            orbit6ElemLb = str2double(handles.orbit6LbText.String);

            utUb = str2double(handles.utUbText.String);
            orbit1ElemUb = str2double(handles.orbit1UbText.String);
            orbit2ElemUb = str2double(handles.orbit2UbText.String);
            orbit3ElemUb = str2double(handles.orbit3UbText.String);
            orbit4ElemUb = str2double(handles.orbit4UbText.String);
            orbit5ElemUb = str2double(handles.orbit5UbText.String);
            orbit6ElemUb = str2double(handles.orbit6UbText.String);

            switch elemSetEnum
                case ElementSetEnum.CartesianElements
                    lb = [utLb orbit1ElemLb orbit2ElemLb orbit3ElemLb orbit4ElemLb orbit5ElemLb orbit6ElemLb];
                    ub = [utUb orbit1ElemUb orbit2ElemUb orbit3ElemUb orbit4ElemUb orbit5ElemUb orbit6ElemUb];

                case ElementSetEnum.KeplerianElements
                    lb = [utLb orbit1ElemLb orbit2ElemLb deg2rad(orbit3ElemLb) deg2rad(orbit4ElemLb) deg2rad(orbit5ElemLb) deg2rad(orbit6ElemLb)];
                    ub = [utUb orbit1ElemUb orbit2ElemUb deg2rad(orbit3ElemUb) deg2rad(orbit4ElemUb) deg2rad(orbit5ElemUb) deg2rad(orbit6ElemUb)];

                case ElementSetEnum.GeographicElements
                    lb = [utLb deg2rad(orbit1ElemLb) deg2rad(orbit2ElemLb) orbit3ElemLb deg2rad(orbit4ElemLb) deg2rad(orbit5ElemLb) orbit6ElemLb];
                    ub = [utUb deg2rad(orbit1ElemUb) deg2rad(orbit2ElemUb) orbit3ElemUb deg2rad(orbit4ElemUb) deg2rad(orbit5ElemUb) orbit6ElemUb];

                case ElementSetEnum.UniversalElements
                    lb = [utLb orbit1ElemLb orbit2ElemLb deg2rad(orbit3ElemLb) deg2rad(orbit4ElemLb) deg2rad(orbit5ElemLb) orbit6ElemLb];
                    ub = [utUb orbit1ElemUb orbit2ElemUb deg2rad(orbit3ElemUb) deg2rad(orbit4ElemUb) deg2rad(orbit5ElemUb) orbit6ElemUb];

                otherwise
                    error('Unknown element set type: %s', class(elemSetEnum));
            end

            for(i=1:length(lb)) %#ok<*NO4LP>
                if(lb(i) > ub(i))
                    temp1 = lb(i);
                    temp2 = ub(i);  

                    lb(i) = temp2;
                    ub(i) = temp1;
                end
            end

            [lbAll, ubAll] = optVar.getAllBndsForVariable();
            lb = [lb, lbAll(8:end)];
            ub = [ub, ubAll(8:end)];

            optVar.setBndsForVariable(lb, ub);

            output = getappdata(hObject, 'output');
            output.output{1} = true;

            close(handles.lvd_EditActionSetKinematicStateGUI);
        end

        function populateStageStatesTab(app)
            stageStateData = app.StagesDryMassListbox.Value;
            if(not(isempty(stageStateData)))
                app.inheritStageStateCheckbox.Value = stageStateData.inheritStageState;

                if(stageStateData.inheritStageStateFrom == InheritStateEnum.InheritFromLastState)
                    app.inheritStageStateButtonGroup.SelectedObject = app.inheritStageStateFromPrevStateRadioBtn;
                elseif(stageStateData.inheritStageStateFrom == InheritStateEnum.InheritFromSpecifiedEvent)
                    app.inheritStageStateButtonGroup.SelectedObject = app.inheritStageStateFromSpecEvtRadioBtn;
                end

                if(ismember(stageStateData.inheritStageStateFromEvent,app.inheritStageStateSpecEvtCombo.ItemsData))
                    app.inheritStageStateSpecEvtCombo.Value = stageStateData.inheritStageStateFromEvent;
                else
                    app.inheritStageStateSpecEvtCombo.Value = app.inheritStageStateSpecEvtCombo.ItemsData(1);
                end

                app.stageActiveCheckbox.Value = stageStateData.stageStateToSet;
            end

            app.setStagesTabEnableDisable();
        end

        function setStagesTabEnableDisable(app)
            stageStateData = app.StagesDryMassListbox.Value;
            if(not(isempty(stageStateData)))
                app.inheritStageStateCheckbox.Enable = true;
                if(app.inheritStageStateCheckbox.Value)
                    app.inheritStageStateFromPrevStateRadioBtn.Enable = true;
                    app.inheritStageStateFromSpecEvtRadioBtn.Enable = true;
                    app.inheritStageStateSpecEvtCombo.Enable = true;
                    app.stageActiveCheckbox.Enable = false;
                else
                    app.inheritStageStateFromPrevStateRadioBtn.Enable = false;
                    app.inheritStageStateFromSpecEvtRadioBtn.Enable = false;
                    app.inheritStageStateSpecEvtCombo.Enable = false;
                    app.stageActiveCheckbox.Enable = true;
                end
            else
                app.inheritStageStateCheckbox.Enable = false;
                app.inheritStageStateFromPrevStateRadioBtn.Enable = false;
                app.inheritStageStateFromSpecEvtRadioBtn.Enable = false;
                app.inheritStageStateSpecEvtCombo.Enable = false;
                app.stageActiveCheckbox.Enable = false;
            end
        end

        function populateEngineStatesTab(app)
            engineStateData = app.EnginesListBox.Value;
            if(not(isempty(engineStateData)))
                app.inheritEngineStateCheckbox.Value = engineStateData.inheritEngineState;

                if(engineStateData.inheritEngineStateFrom == InheritStateEnum.InheritFromLastState)
                    app.inheritEngineStateButtonGroup.SelectedObject = app.inheritEngineStateFromPrevStateRadioBtn;
                elseif(engineStateData.inheritEngineStateFrom == InheritStateEnum.InheritFromSpecifiedEvent)
                    app.inheritEngineStateButtonGroup.SelectedObject = app.inheritEngineStateFromSpecEvtRadioBtn;
                end

                if(ismember(engineStateData.inheritEngineStateFromEvent,app.inheritEngineStateSpecEvtCombo.ItemsData))
                    app.inheritEngineStateSpecEvtCombo.Value = engineStateData.inheritEngineStateFromEvent;
                else
                    app.inheritEngineStateSpecEvtCombo.Value = app.inheritEngineStateSpecEvtCombo.ItemsData(1);
                end

                app.engineActiveCheckbox.Value = engineStateData.engineStateToSet;
            end

            app.setEnginesTabEnableDisable();
        end

        function setEnginesTabEnableDisable(app)
            engineStateData = app.EnginesListBox.Value;
            if(not(isempty(engineStateData)))
                app.inheritEngineStateCheckbox.Enable = true;
                if(app.inheritEngineStateCheckbox.Value)
                    app.inheritEngineStateFromPrevStateRadioBtn.Enable = true;
                    app.inheritEngineStateFromSpecEvtRadioBtn.Enable = true;
                    app.inheritEngineStateSpecEvtCombo.Enable = true;
                    app.engineActiveCheckbox.Enable = false;
                else
                    app.inheritEngineStateFromPrevStateRadioBtn.Enable = false;
                    app.inheritEngineStateFromSpecEvtRadioBtn.Enable = false;
                    app.inheritEngineStateSpecEvtCombo.Enable = false;
                    app.engineActiveCheckbox.Enable = true;
                end
            else
                app.inheritEngineStateCheckbox.Enable = false;
                app.inheritEngineStateFromPrevStateRadioBtn.Enable = false;
                app.inheritEngineStateFromSpecEvtRadioBtn.Enable = false;
                app.inheritEngineStateSpecEvtCombo.Enable = false;
                app.engineActiveCheckbox.Enable = false;
            end
        end

        function populateTankStatesTab(app)
            tankStateData = app.TanksListBox.Value;
            if(not(isempty(tankStateData)))
                app.inheritTankMassCheckbox.Value = tankStateData.inheritTankState;

                if(tankStateData.inheritTankStateFrom == InheritStateEnum.InheritFromLastState)
                    app.inheritTankMassButtonGroup.SelectedObject = app.inheritTankMassFromPrevStateRadioBtn;
                elseif(tankStateData.inheritTankStateFrom == InheritStateEnum.InheritFromSpecifiedEvent)
                    app.inheritTankMassButtonGroup.SelectedObject = app.inheritTankMassFromSpecEvtRadioBtn;
                end

                if(ismember(tankStateData.inheritTankStateFromEvent,app.inheritTankMassSpecEvtCombo.ItemsData))
                    app.inheritTankMassSpecEvtCombo.Value = tankStateData.inheritTankStateFromEvent;
                else
                    app.inheritTankMassSpecEvtCombo.Value = app.inheritTankMassSpecEvtCombo.ItemsData(1);
                end

                app.TankMassText.Value = fullAccNum2Str(tankStateData.tankStateMassToSet);

                if(isempty(tankStateData.optVar))
                    var = SetKinematicStateTankStateOptimVar(tankStateData);
                else
                    var = tankStateData.optVar;
                end

                useTf = var.useTf;
                lb = var.lwrBnd;
                ub = var.uprBnd;

                app.optTankMassCheckbox.Value = useTf;
                app.tankDryMassLbText.Value = fullAccNum2Str(lb);
                app.tankDryMassUbText.Value = fullAccNum2Str(ub);
            end

            app.setTanksTabEnableDisable();
        end

        function setTanksTabEnableDisable(app)
            tankStateData = app.TanksListBox.Value;
            if(not(isempty(tankStateData)))
                app.inheritTankMassCheckbox.Enable = true;
                if(app.inheritTankMassCheckbox.Value)
                    app.inheritTankMassFromPrevStateRadioBtn.Enable = true;
                    app.inheritTankMassFromSpecEvtRadioBtn.Enable = true;
                    app.inheritTankMassSpecEvtCombo.Enable = true;
                    app.TankMassText.Enable = false;
                    app.optTankMassCheckbox.Enable = false;
                    app.tankDryMassLbText.Enable = false;
                    app.tankDryMassUbText.Enable = false;
                else
                    app.inheritTankMassFromPrevStateRadioBtn.Enable = false;
                    app.inheritTankMassFromSpecEvtRadioBtn.Enable = false;
                    app.inheritTankMassSpecEvtCombo.Enable = false;
                    app.TankMassText.Enable = true;
                    app.optTankMassCheckbox.Enable = true;
                    if(app.optTankMassCheckbox.Value)
                        app.tankDryMassLbText.Enable = true;
                        app.tankDryMassUbText.Enable = true;
                    else
                        app.tankDryMassLbText.Enable = false;
                        app.tankDryMassUbText.Enable = false;
                    end
                end
            else
                app.inheritTankMassCheckbox.Enable = false;
                app.inheritTankMassFromPrevStateRadioBtn.Enable = false;
                app.inheritTankMassFromSpecEvtRadioBtn.Enable = false;
                app.inheritTankMassSpecEvtCombo.Enable = false;
                app.TankMassText.Enable = false;
                app.optTankMassCheckbox.Enable = false;
                app.tankDryMassLbText.Enable = false;
                app.tankDryMassUbText.Enable = false;
            end
        end

        function populateEpsSrcStatesTab(app)
            epsSrcStateData = app.EpsSrcListbox.Value;
            if(not(isempty(epsSrcStateData)))
                app.inheritEpsSrcStateCheckbox.Value = epsSrcStateData.inheritSrcState;

                if(epsSrcStateData.inheritSrcStateFrom == InheritStateEnum.InheritFromLastState)
                    app.inheritEpsSrcStateButtonGroup.SelectedObject = app.inheritEpsSrcStateFromPrevStateRadioBtn;
                elseif(epsSrcStateData.inheritSrcStateFrom == InheritStateEnum.InheritFromSpecifiedEvent)
                    app.inheritEpsSrcStateButtonGroup.SelectedObject = app.inheritEpsSrcStateFromSpecEvtRadioBtn;
                end

                if(ismember(epsSrcStateData.inheritSrcStateFromEvent,app.inheritEpsSrcStateSpecEvtCombo.ItemsData))
                    app.inheritEpsSrcStateSpecEvtCombo.Value = epsSrcStateData.inheritSrcStateFromEvent;
                else
                    app.inheritEpsSrcStateSpecEvtCombo.Value = app.inheritEpsSrcStateSpecEvtCombo.ItemsData(1);
                end

                app.epsSrcActiveCheckbox.Value = epsSrcStateData.srcStateToSet;
            end

            app.setEpsSrcTabEnableDisable();
        end

        function setEpsSrcTabEnableDisable(app)
            epsSrcStateData = app.EpsSrcListbox.Value;
            if(not(isempty(epsSrcStateData)))
                app.inheritEpsSrcStateCheckbox.Enable = true;
                if(app.inheritEpsSrcStateCheckbox.Value)
                    app.inheritEpsSrcStateFromPrevStateRadioBtn.Enable = true;
                    app.inheritEpsSrcStateFromSpecEvtRadioBtn.Enable = true;
                    app.inheritEpsSrcStateSpecEvtCombo.Enable = true;
                    app.epsSrcActiveCheckbox.Enable = false;
                else
                    app.inheritEpsSrcStateFromPrevStateRadioBtn.Enable = false;
                    app.inheritEpsSrcStateFromSpecEvtRadioBtn.Enable = false;
                    app.inheritEpsSrcStateSpecEvtCombo.Enable = false;
                    app.epsSrcActiveCheckbox.Enable = true;
                end
            else
                app.inheritEpsSrcStateCheckbox.Enable = false;
                app.inheritEpsSrcStateFromPrevStateRadioBtn.Enable = false;
                app.inheritEpsSrcStateFromSpecEvtRadioBtn.Enable = false;
                app.inheritEpsSrcStateSpecEvtCombo.Enable = false;
                app.epsSrcActiveCheckbox.Enable = false;
            end
        end

        function populateEpsSinkStatesTab(app)
            epsSinkStateData = app.EpsSinkListbox.Value;
            if(not(isempty(epsSinkStateData)))
                app.inheritEpsSinkStateCheckbox.Value = epsSinkStateData.inheritSinkState;

                if(epsSinkStateData.inheritSinkStateFrom == InheritStateEnum.InheritFromLastState)
                    app.inheritEpsSinkStateButtonGroup.SelectedObject = app.inheritEpsSinkStateFromPrevStateRadioBtn;
                elseif(epsSinkStateData.inheritSinkStateFrom == InheritStateEnum.InheritFromSpecifiedEvent)
                    app.inheritEpsSinkStateButtonGroup.SelectedObject = app.inheritEpsSinkStateFromSpecEvtRadioBtn;
                end

                if(ismember(epsSinkStateData.inheritSinkStateFromEvent,app.inheritEpsSinkStateSpecEvtCombo.ItemsData))
                    app.inheritEpsSinkStateSpecEvtCombo.Value = epsSinkStateData.inheritSinkStateFromEvent;
                else
                    app.inheritEpsSinkStateSpecEvtCombo.Value = app.inheritEpsSinkStateSpecEvtCombo.ItemsData(1);
                end

                app.epsSinkActiveCheckbox.Value = epsSinkStateData.sinkStateToSet;
            end

            app.setEpsSinkTabEnableDisable();
        end

        function setEpsSinkTabEnableDisable(app)
            epsSinkStateData = app.EpsSinkListbox.Value;
            if(not(isempty(epsSinkStateData)))
                app.inheritEpsSinkStateCheckbox.Enable = true;
                if(app.inheritEpsSinkStateCheckbox.Value)
                    app.inheritEpsSinkStateFromPrevStateRadioBtn.Enable = true;
                    app.inheritEpsSinkStateFromSpecEvtRadioBtn.Enable = true;
                    app.inheritEpsSinkStateSpecEvtCombo.Enable = true;
                    app.epsSinkActiveCheckbox.Enable = false;
                else
                    app.inheritEpsSinkStateFromPrevStateRadioBtn.Enable = false;
                    app.inheritEpsSinkStateFromSpecEvtRadioBtn.Enable = false;
                    app.inheritEpsSinkStateSpecEvtCombo.Enable = false;
                    app.epsSinkActiveCheckbox.Enable = true;
                end
            else
                app.inheritEpsSinkStateCheckbox.Enable = false;
                app.inheritEpsSinkStateFromPrevStateRadioBtn.Enable = false;
                app.inheritEpsSinkStateFromSpecEvtRadioBtn.Enable = false;
                app.inheritEpsSinkStateSpecEvtCombo.Enable = false;
                app.epsSinkActiveCheckbox.Enable = false;
            end
        end

        function populateEpsStorageStatesTab(app)
            epsStorageStateData = app.EpsStorageListbox.Value;
            if(not(isempty(epsStorageStateData)))
                app.inheritEpsStorageStateCheckbox.Value = epsStorageStateData.inheritStorageState;

                if(epsStorageStateData.inheritStorageStateFrom == InheritStateEnum.InheritFromLastState)
                    app.inheritEpsStorageStateButtonGroup.SelectedObject = app.inheritEpsStorageStateFromPrevStateRadioBtn;
                elseif(epsStorageStateData.inheritStorageStateFrom == InheritStateEnum.InheritFromSpecifiedEvent)
                    app.inheritEpsStorageStateButtonGroup.SelectedObject = app.inheritEpsStorageStateFromSpecEvtRadioBtn;
                end

                if(ismember(epsStorageStateData.inheritStorageStateFromEvent,app.inheritEpsStorageStateSpecEvtCombo.ItemsData))
                    app.inheritEpsStorageStateSpecEvtCombo.Value = epsStorageStateData.inheritStorageStateFromEvent;
                else
                    app.inheritEpsStorageStateSpecEvtCombo.Value = app.inheritEpsStorageStateSpecEvtCombo.ItemsData(1);
                end

                app.epsStorageActiveCheckbox.Value = epsStorageStateData.storageStateToSet;
                app.StorageSoCText.Value = fullAccNum2Str(epsStorageStateData.socToSet);

                if(isempty(epsStorageStateData.optVar))
                    var = SetKinematicStateEpsStorageStateOptimVar(epsStorageStateData);
                else
                    var = epsStorageStateData.optVar;
                end

                useTf = var.useTf;
                lb = var.lwrBnd;
                ub = var.uprBnd;

                app.optStorageSoCCheckbox.Value = useTf;
                app.storageSoCLbText.Value = fullAccNum2Str(lb);
                app.storageSoCUbText.Value = fullAccNum2Str(ub);
            end

            app.setEpsStoreTabEnableDisable();
        end

        function setEpsStoreTabEnableDisable(app)
            epsStorageStateData = app.EpsStorageListbox.Value;
            if(not(isempty(epsStorageStateData)))
                app.inheritEpsStorageStateCheckbox.Enable = true;
                if(app.inheritEpsStorageStateCheckbox.Value)
                    app.inheritEpsStorageStateFromPrevStateRadioBtn.Enable = true;
                    app.inheritEpsStorageStateFromSpecEvtRadioBtn.Enable = true;
                    app.inheritEpsStorageStateSpecEvtCombo.Enable = true;
                    app.epsStorageActiveCheckbox.Enable = false;
                    app.StorageSoCText.Enable = false;
                    app.optStorageSoCCheckbox.Enable = false;
                    app.storageSoCLbText.Enable = false;
                    app.storageSoCUbText.Enable = false;
                else
                    app.inheritEpsStorageStateFromPrevStateRadioBtn.Enable = false;
                    app.inheritEpsStorageStateFromSpecEvtRadioBtn.Enable = false;
                    app.inheritEpsStorageStateSpecEvtCombo.Enable = false;
                    app.epsStorageActiveCheckbox.Enable = true;
                    app.StorageSoCText.Enable = true;
                    app.optStorageSoCCheckbox.Enable = true;
                    if(app.optStorageSoCCheckbox.Value)
                        app.storageSoCLbText.Enable = true;
                        app.storageSoCUbText.Enable = true;
                    else
                        app.storageSoCLbText.Enable = false;
                        app.storageSoCUbText.Enable = false;
                    end
                end
            else
                app.inheritEpsStorageStateCheckbox.Enable = false;
                app.inheritEpsStorageStateFromPrevStateRadioBtn.Enable = false;
                app.inheritEpsStorageStateFromSpecEvtRadioBtn.Enable = false;
                app.inheritEpsStorageStateSpecEvtCombo.Enable = false;
                app.epsStorageActiveCheckbox.Enable = false;
                app.StorageSoCText.Enable = false;
                app.optStorageSoCCheckbox.Enable = false;
                app.storageSoCLbText.Enable = false;
                app.storageSoCUbText.Enable = false;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditActionSetKinematicStateGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            centerUIFigure(app.lvd_EditActionSetKinematicStateGUI);

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditActionSetKinematicStateGUI (see VARARGIN)

            % Choose default command line output for lvd_EditActionSetKinematicStateGUI
            handles.output = hObject;

            action = varargin{1};
            setappdata(hObject, 'action', action);

            lvdData = varargin{2};
            setappdata(hObject, 'lvdData', lvdData);

            output = varargin{3};
            setappdata(hObject, 'output', output);

            action.stateLog = lvdData.stateLog;

            populateGUI(app, handles, action, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditActionSetKinematicStateGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditActionSetKinematicStateGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditActionSetKinematicStateGUI);
        end

        % Menu selected function: copyOrbitToClipboardMenu
        function copyOrbitToClipboardMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to copyOrbitToClipboardMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % 	lvdData = getappdata(handles.lvd_EditActionSetKinematicStateGUI,'lvdData');
            %     celBodyData = lvdData.celBodyData;

            %     contents = cellstr(get(handles.centralBodyCombo,'String'));
            %     selected = strtrim(contents{get(handles.centralBodyCombo,'Value')});
            %     bodyInfo = celBodyData.(lower(selected));

            bodyInfo = getSelectedBodyInfo(app, handles);

            o1V = handles.orbit1Text.String;
            o2V = handles.orbit2Text.String;
            o3V = handles.orbit3Text.String;
            o4V = handles.orbit4Text.String;
            o5V = handles.orbit5Text.String;
            o6V = handles.orbit6Text.String;

            contents = cellstr(get(handles.refFrameTypeCombo,'String'));
            selFrameType = contents{get(handles.refFrameTypeCombo,'Value')};
            enum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);
            framEnumOrig = enum;
            if(not(enum == ReferenceFrameEnum.BodyCenteredInertial))
                enum = ReferenceFrameEnum.BodyCenteredInertial;
                ind = ReferenceFrameEnum.getIndForName(enum.name);
                handles.refFrameTypeCombo.Value = ind;

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                refFrameTypeCombo_Callback(app, evt);
            end

            contents = cellstr(get(handles.elementSetCombo,'String'));
            selOrbitType = contents{get(handles.elementSetCombo,'Value')};
            [~,enum] = ElementSetEnum.getIndForName(selOrbitType);
            elemSetEnumOrig = enum;
            if(not(enum == ElementSetEnum.KeplerianElements))
                enum = ElementSetEnum.KeplerianElements;
                ind = ElementSetEnum.getIndForName(enum.name);
                handles.elementSetCombo.Value = ind;

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                elementSetCombo_Callback(app, evt);
            end

            copyOrbitToClipboardFromText(handles.utText, handles.orbit1Text, handles.orbit2Text, ...
                handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, ...
                handles.orbit6Text, true, bodyInfo.id);

            ind = ElementSetEnum.getIndForName(elemSetEnumOrig.name);
            handles.elementSetCombo.Value = ind;
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            elementSetCombo_Callback(app, evt);

            ind = ReferenceFrameEnum.getIndForName(framEnumOrig.name);
            handles.refFrameTypeCombo.Value = ind;
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            refFrameTypeCombo_Callback(app, evt);

            handles.orbit1Text.String = o1V;
            handles.orbit2Text.String = o2V;
            handles.orbit3Text.String = o3V;
            handles.orbit4Text.String = o4V;
            handles.orbit5Text.String = o5V;
            handles.orbit6Text.String = o6V;
        end

        % Value changed function: elementSetCombo
        function elementSetCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.elementSetCombo;

            % hObject    handle to elementSetCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns elementSetCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from elementSetCombo
            contents = cellstr(get(hObject,'String'));
            sel = contents{get(hObject,'Value')};
            [~, elemSetEnum] = ElementSetEnum.getIndForName(sel);

            elemNames = elemSetEnum.elemNames;
            unitNames = elemSetEnum.unitNames;

            handles.orbit1Label.String = elemNames{1};
            handles.orbit2Label.String = elemNames{2};
            handles.orbit3Label.String = elemNames{3};
            handles.orbit4Label.String = elemNames{4};
            handles.orbit5Label.String = elemNames{5};
            handles.orbit6Label.String = elemNames{6};

            handles.orbit1UnitLabel.String = unitNames{1};
            handles.orbit2UnitLabel.String = unitNames{2};
            handles.orbit3UnitLabel.String = unitNames{3};
            handles.orbit4UnitLabel.String = unitNames{4};
            handles.orbit5UnitLabel.String = unitNames{5};
            handles.orbit6UnitLabel.String = unitNames{6};

            curElemSet = getappdata(handles.lvd_EditActionSetKinematicStateGUI,'curElemSet');

            try
                switch elemSetEnum
                    case ElementSetEnum.CartesianElements
                        newElemSet = curElemSet.convertToCartesianElementSet();

                    case ElementSetEnum.KeplerianElements
                        newElemSet = curElemSet.convertToKeplerianElementSet();

                    case ElementSetEnum.GeographicElements
                        newElemSet = curElemSet.convertToGeographicElementSet();

                    case ElementSetEnum.UniversalElements
                        newElemSet = curElemSet.convertToUniversalElementSet();

                    otherwise
                        error('Unknown element set type: %s', class(elemSetEnum));
                end

                elemVect = newElemSet.getElementVector();

                if(any(isnan(elemVect)) || any(not(isfinite(elemVect))))
                    error('State conversion resulted in NaN or Inf');
                end

                handles.orbit1Text.String = fullAccNum2Str(elemVect(1));
                handles.orbit2Text.String = fullAccNum2Str(elemVect(2));
                handles.orbit3Text.String = fullAccNum2Str(elemVect(3));
                handles.orbit4Text.String = fullAccNum2Str(elemVect(4));
                handles.orbit5Text.String = fullAccNum2Str(elemVect(5));
                handles.orbit6Text.String = fullAccNum2Str(elemVect(6));

                setappdata(handles.lvd_EditActionSetKinematicStateGUI,'curElemSet', newElemSet);

            catch ME %#ok<NASGU>
                updateValuesInState(app, handles);
                uialert(app.lvd_EditActionSetKinematicStateGUI, 'Error converting state to new element set.', 'Conversion Error', "Icon","error")
            end
        end

        % Menu selected function: enterUTAsDateTimeContextMenu
        function enterUTAsDateTimeContextMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to enterUTAsDateTimeContextMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            output = AppDesignerGUIOutput({-1});
            enterUTAsDateTimeGUI_App(str2double(get(gco, 'Value')), output);
            secUT = output.output{1};
            
            if(secUT >= 0)
                set(gco, 'String', fullAccNum2Str(secUT));

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                utText_Callback(app, evt);
            end
        end

        % Menu selected function: getOrbitFromKSPActiveVesselMenu
        function getOrbitFromKSPActiveVesselMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getOrbitFromKSPActiveVesselMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            contents = cellstr(get(handles.refFrameTypeCombo,'String'));
            selFrameType = contents{get(handles.refFrameTypeCombo,'Value')};
            enum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);
            if(not(enum == ReferenceFrameEnum.BodyCenteredInertial))
                enum = ReferenceFrameEnum.BodyCenteredInertial;
                ind = ReferenceFrameEnum.getIndForName(enum.name);
                handles.refFrameTypeCombo.Value = ind;

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                refFrameTypeCombo_Callback(app, evt);
            end

            contents = cellstr(get(handles.elementSetCombo,'String'));
            selOrbitType = contents{get(handles.elementSetCombo,'Value')};
            [~,enum] = ElementSetEnum.getIndForName(selOrbitType);
            if(not(enum == ElementSetEnum.KeplerianElements))
                enum = ElementSetEnum.KeplerianElements;
                ind = ElementSetEnum.getIndForName(enum.name);
                handles.elementSetCombo.Value = ind;

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                elementSetCombo_Callback(app, evt);
            end

            refBodyID = orbitPanelGetOrbitFromKSPTOTConnectActiveVesselCallBack(handles.orbit1Text, handles.orbit2Text, handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, handles.orbit6Text, handles.utText);
            tru = computeTrueAnomFromMean(deg2rad(str2double(get(handles.orbit6Text,'String'))), str2double(get(handles.orbit2Text,'String')));
            set(handles.orbit6Text,'String',fullAccNum2Str(rad2deg(tru)));

            if(~isempty(refBodyID) && isnumeric(refBodyID))
                lvdData = getappdata(handles.lvd_EditActionSetKinematicStateGUI,'lvdData');
                celBodyData = lvdData.celBodyData;
                bodyInfo = getBodyInfoByNumber(refBodyID, celBodyData);

                curElemSet = getCurrentElemSetFromExistingValues(app, handles);
                setappdata(handles.lvd_EditActionSetKinematicStateGUI,'curElemSet',curElemSet);

                %         curElemSet = getappdata(handles.lvd_EditActionSetKinematicStateGUI,'curElemSet');
                curElemSet.frame.setOriginBody(bodyInfo);
                updateStateDueToFrameChange(app, handles, AbstractReferenceFrame.empty(1,0));

                %         value = findValueFromComboBox(bodyInfo.name, handles.centralBodyCombo);
                %         set(handles.centralBodyCombo,'Value',value);
            end
        end

        % Menu selected function: 
        % getOrbitFromKSPTOTConnectContextMenu
        function getOrbitFromKSPTOTConnectContextMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getOrbitFromKSPTOTConnectContextMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            contents = cellstr(get(handles.refFrameTypeCombo,'String'));
            selFrameType = contents{get(handles.refFrameTypeCombo,'Value')};
            enum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);
            if(not(enum == ReferenceFrameEnum.BodyCenteredInertial))
                enum = ReferenceFrameEnum.BodyCenteredInertial;
                ind = ReferenceFrameEnum.getIndForName(enum.name);
                handles.refFrameTypeCombo.Value = ind;

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                refFrameTypeCombo_Callback(app, evt);
            end

            contents = cellstr(get(handles.elementSetCombo,'String'));
            selOrbitType = contents{get(handles.elementSetCombo,'Value')};
            [~,enum] = ElementSetEnum.getIndForName(selOrbitType);
            if(not(enum == ElementSetEnum.KeplerianElements))
                enum = ElementSetEnum.KeplerianElements;
                ind = ElementSetEnum.getIndForName(enum.name);
                handles.elementSetCombo.Value = ind;

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                elementSetCombo_Callback(app, evt);
            end

            refBodyID = orbitPanelGetOrbitFromKSPTOTConnectCallBack(handles.orbit1Text, handles.orbit2Text, handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, handles.orbit6Text, handles.utText);
            tru = computeTrueAnomFromMean(deg2rad(str2double(get(handles.orbit6Text,'String'))), str2double(get(handles.orbit2Text,'String')));
            set(handles.orbit6Text,'String',fullAccNum2Str(rad2deg(tru)));

            if(~isempty(refBodyID) && isnumeric(refBodyID))
                lvdData = getappdata(handles.lvd_EditActionSetKinematicStateGUI,'lvdData');
                celBodyData = lvdData.celBodyData;
                bodyInfo = getBodyInfoByNumber(refBodyID, celBodyData);

                curElemSet = getCurrentElemSetFromExistingValues(app, handles);
                setappdata(handles.lvd_EditActionSetKinematicStateGUI,'curElemSet',curElemSet);

                %         curElemSet = getappdata(handles.lvd_EditActionSetKinematicStateGUI,'curElemSet');
                curElemSet.frame.setOriginBody(bodyInfo);
                updateStateDueToFrameChange(app, handles, AbstractReferenceFrame.empty(1,0));

                %         value = findValueFromComboBox(bodyInfo.name, handles.centralBodyCombo);
                %         set(handles.centralBodyCombo,'Value',value);
            end
        end

        % Menu selected function: getOrbitFromSFSFileContextMenu
        function getOrbitFromSFSFileContextMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getOrbitFromSFSFileContextMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            contents = cellstr(get(handles.refFrameTypeCombo,'String'));
            selFrameType = contents{get(handles.refFrameTypeCombo,'Value')};
            enum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);
            if(not(enum == ReferenceFrameEnum.BodyCenteredInertial))
                enum = ReferenceFrameEnum.BodyCenteredInertial;
                ind = ReferenceFrameEnum.getIndForName(enum.name);
                handles.refFrameTypeCombo.Value = ind;

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                refFrameTypeCombo_Callback(app, evt);
            end

            contents = cellstr(get(handles.elementSetCombo,'String'));
            selOrbitType = contents{get(handles.elementSetCombo,'Value')};
            [~,enum] = ElementSetEnum.getIndForName(selOrbitType);
            if(not(enum == ElementSetEnum.KeplerianElements))
                enum = ElementSetEnum.KeplerianElements;
                ind = ElementSetEnum.getIndForName(enum.name);
                handles.elementSetCombo.Value = ind;

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                elementSetCombo_Callback(app, evt);
            end

            refBodyID = orbitPanelGetOrbitFromSFSContextCallBack([], handles.orbit1Text, handles.orbit2Text, handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, handles.orbit6Text, handles.utText);
            tru = computeTrueAnomFromMean(deg2rad(str2double(get(handles.orbit6Text,'String'))), str2double(get(handles.orbit2Text,'String')));
            set(handles.orbit6Text,'String',fullAccNum2Str(rad2deg(tru)));

            if(~isempty(refBodyID) && isnumeric(refBodyID))
                lvdData = getappdata(handles.lvd_EditActionSetKinematicStateGUI,'lvdData');
                celBodyData = lvdData.celBodyData;
                bodyInfo = getBodyInfoByNumber(refBodyID, celBodyData);

                curElemSet = getCurrentElemSetFromExistingValues(app, handles);
                setappdata(handles.lvd_EditActionSetKinematicStateGUI,'curElemSet',curElemSet);

                %         curElemSet = getappdata(handles.lvd_EditActionSetKinematicStateGUI,'curElemSet');
                curElemSet.frame.setOriginBody(bodyInfo);
                updateStateDueToFrameChange(app, handles, AbstractReferenceFrame.empty(1,0));

                %         value = findValueFromComboBox(bodyInfo.name, handles.centralBodyCombo);
                %         set(handles.centralBodyCombo,'Value',value);
            end
        end

        % Selection changed function: 
        % inheritOtherStateElementsButtonGroup
        function inheritOtherStateElementsButtonGroup_SelectionChangedFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.inheritOtherStateElementsButtonGroup;

            % hObject    handle to the selected object in inheritOtherStateElementsButtonGroup
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if(hObject == handles.inheritOtherStateElementsFromPrevStateRadioBtn)
                handles.inheritOtherStateElementsEvtCombo.Enable = 'off';
            else
                handles.inheritOtherStateElementsEvtCombo.Enable = 'on';
            end
        end

        % Callback function
        function inheritStageStatesButtonGroup_SelectionChangedFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.inheritStageStatesButtonGroup;

            % hObject    handle to the selected object in inheritStageStatesButtonGroup
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if(hObject == handles.inheritStageStatesFromPrevStateRadioBtn)
                handles.inheritStageStatesSpecEvtCombo.Enable = 'off';
            else
                handles.inheritStageStatesSpecEvtCombo.Enable = 'on';
            end
        end

        % Callback function
        function inheritStageStatesCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to inheritStageStatesCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of inheritStageStatesCheckbox
            if(not(get(hObject,'Value')))

                handles.inheritStageStatesFromPrevStateRadioBtn.Enable = 'off';
                handles.inheritStageStatesFromSpecEvtRadioBtn.Enable = 'off';
                handles.inheritStageStatesSpecEvtCombo.Enable = 'off';
            else

                handles.inheritStageStatesFromPrevStateRadioBtn.Enable = 'on';
                handles.inheritStageStatesFromSpecEvtRadioBtn.Enable = 'on';

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                inheritStageStatesButtonGroup_SelectionChangedFcn(app, evt);
            end
        end

        % Selection changed function: inheritStateButtonGroup
        function inheritStateButtonGroup_SelectionChangedFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.inheritStateButtonGroup;

            % hObject    handle to the selected object in inheritStateButtonGroup
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if(hObject == handles.inheritStateFromPrevStateRadioBtn)
                handles.inheritStateSpecEvtCombo.Enable = 'off';
            else
                handles.inheritStateSpecEvtCombo.Enable = 'on';
            end
        end

        % Value changed function: inheritStateCheckbox
        function inheritStateCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.inheritStateCheckbox;

            % hObject    handle to inheritStateCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of inheritStateCheckbox
            if(not(get(hObject,'Value')))
                handles.orbit1Text.Enable = 'on';
                handles.optOrbit1Checkbox.Enable = 'on';

                handles.orbit2Text.Enable = 'on';
                handles.optOrbit2Checkbox.Enable = 'on';

                handles.orbit3Text.Enable = 'on';
                handles.optOrbit3Checkbox.Enable = 'on';

                handles.orbit4Text.Enable = 'on';
                handles.optOrbit4Checkbox.Enable = 'on';

                handles.orbit5Text.Enable = 'on';
                handles.optOrbit5Checkbox.Enable = 'on';

                handles.orbit6Text.Enable = 'on';
                handles.optOrbit6Checkbox.Enable = 'on';

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                optOrbit1Checkbox_Callback(app, evt);
                optOrbit2Checkbox_Callback(app, evt);
                optOrbit3Checkbox_Callback(app, evt);
                optOrbit4Checkbox_Callback(app, evt);
                optOrbit5Checkbox_Callback(app, evt);
                optOrbit6Checkbox_Callback(app, evt);

                handles.inheritStateFromPrevStateRadioBtn.Enable = 'off';
                handles.inheritStateFromSpecEvtRadioBtn.Enable = 'off';
                handles.inheritStateSpecEvtCombo.Enable = 'off';
            else
                handles.orbit1Text.Enable = 'off';
                handles.optOrbit1Checkbox.Enable = 'off';

                handles.orbit2Text.Enable = 'off';
                handles.optOrbit2Checkbox.Enable = 'off';

                handles.orbit3Text.Enable = 'off';
                handles.optOrbit3Checkbox.Enable = 'off';

                handles.orbit4Text.Enable = 'off';
                handles.optOrbit4Checkbox.Enable = 'off';

                handles.orbit5Text.Enable = 'off';
                handles.optOrbit5Checkbox.Enable = 'off';

                handles.orbit6Text.Enable = 'off';
                handles.optOrbit6Checkbox.Enable = 'off';

                handles.orbit1LbText.Enable = 'off';
                handles.orbit1UbText.Enable = 'off';

                handles.orbit2LbText.Enable = 'off';
                handles.orbit2UbText.Enable = 'off';

                handles.orbit3LbText.Enable = 'off';
                handles.orbit3UbText.Enable = 'off';

                handles.orbit4LbText.Enable = 'off';
                handles.orbit4UbText.Enable = 'off';

                handles.orbit5LbText.Enable = 'off';
                handles.orbit5UbText.Enable = 'off';

                handles.orbit6LbText.Enable = 'off';
                handles.orbit6UbText.Enable = 'off';

                handles.inheritStateFromPrevStateRadioBtn.Enable = 'on';
                handles.inheritStateFromSpecEvtRadioBtn.Enable = 'on';

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                inheritStateButtonGroup_SelectionChangedFcn(app, evt);
            end
        end

        % Selection changed function: inheritTimeButtonGroup
        function inheritTimeButtonGroup_SelectionChangedFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.inheritTimeButtonGroup;

            % hObject    handle to the selected object in inheritTimeButtonGroup
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if(hObject == handles.inheritTimeFromPrevStateRadioBtn)
                handles.inheritTimeSpecEvtCombo.Enable = 'off';
            else
                handles.inheritTimeSpecEvtCombo.Enable = 'on';
            end
        end

        % Value changed function: inheritTimeCheckbox
        function inheritTimeCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.inheritTimeCheckbox;

            % hObject    handle to inheritTimeCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of inheritTimeCheckbox
            if(not(get(hObject,'Value')))
                handles.utText.Enable = 'on';
                handles.optUtCheckbox.Enable = 'on';

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                optUtCheckbox_Callback(app, evt);

                handles.inheritTimeFromPrevStateRadioBtn.Enable = 'off';
                handles.inheritTimeFromSpecEvtRadioBtn.Enable = 'off';
                handles.inheritTimeSpecEvtCombo.Enable = 'off';
            else
                handles.utText.Enable = 'off';
                handles.optUtCheckbox.Enable = 'off';

                handles.utLbText.Enable = 'off';
                handles.utUbText.Enable = 'off';

                handles.inheritTimeFromPrevStateRadioBtn.Enable = 'on';
                handles.inheritTimeFromSpecEvtRadioBtn.Enable = 'on';

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                inheritTimeButtonGroup_SelectionChangedFcn(app, evt);
            end
        end

        % Window key press function: 
        % lvd_EditActionSetKinematicStateGUI
        function lvd_EditActionSetKinematicStateGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditActionSetKinematicStateGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditActionSetKinematicStateGUI);
            end
        end

        % Value changed function: optOrbit1Checkbox
        function optOrbit1Checkbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.optOrbit1Checkbox;

            % hObject    handle to optOrbit1Checkbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of optOrbit1Checkbox
            if(get(hObject,'Value') && not(get(handles.inheritStateCheckbox,'Value')))
                handles.orbit1LbText.Enable = 'on';
                handles.orbit1UbText.Enable = 'on';
            else
                handles.orbit1LbText.Enable = 'off';
                handles.orbit1UbText.Enable = 'off';
            end
        end

        % Value changed function: optOrbit2Checkbox
        function optOrbit2Checkbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.optOrbit2Checkbox;

            % hObject    handle to optOrbit2Checkbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of optOrbit2Checkbox
            if(get(hObject,'Value') && not(get(handles.inheritStateCheckbox,'Value')))
                handles.orbit2LbText.Enable = 'on';
                handles.orbit2UbText.Enable = 'on';
            else
                handles.orbit2LbText.Enable = 'off';
                handles.orbit2UbText.Enable = 'off';
            end
        end

        % Value changed function: optOrbit3Checkbox
        function optOrbit3Checkbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.optOrbit3Checkbox;

            % hObject    handle to optOrbit3Checkbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of optOrbit3Checkbox
            if(get(hObject,'Value') && not(get(handles.inheritStateCheckbox,'Value')))
                handles.orbit3LbText.Enable = 'on';
                handles.orbit3UbText.Enable = 'on';
            else
                handles.orbit3LbText.Enable = 'off';
                handles.orbit3UbText.Enable = 'off';
            end
        end

        % Value changed function: optOrbit4Checkbox
        function optOrbit4Checkbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.optOrbit4Checkbox;

            % hObject    handle to optOrbit4Checkbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of optOrbit4Checkbox
            if(get(hObject,'Value') && not(get(handles.inheritStateCheckbox,'Value')))
                handles.orbit4LbText.Enable = 'on';
                handles.orbit4UbText.Enable = 'on';
            else
                handles.orbit4LbText.Enable = 'off';
                handles.orbit4UbText.Enable = 'off';
            end
        end

        % Value changed function: optOrbit5Checkbox
        function optOrbit5Checkbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.optOrbit5Checkbox;

            % hObject    handle to optOrbit5Checkbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of optOrbit5Checkbox
            if(get(hObject,'Value') && not(get(handles.inheritStateCheckbox,'Value')))
                handles.orbit5LbText.Enable = 'on';
                handles.orbit5UbText.Enable = 'on';
            else
                handles.orbit5LbText.Enable = 'off';
                handles.orbit5UbText.Enable = 'off';
            end
        end

        % Value changed function: optOrbit6Checkbox
        function optOrbit6Checkbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.optOrbit6Checkbox;

            % hObject    handle to optOrbit6Checkbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of optOrbit6Checkbox
            if(get(hObject,'Value') && not(get(handles.inheritStateCheckbox,'Value')))
                handles.orbit6LbText.Enable = 'on';
                handles.orbit6UbText.Enable = 'on';
            else
                handles.orbit6LbText.Enable = 'off';
                handles.orbit6UbText.Enable = 'off';
            end
        end

        % Value changed function: optUtCheckbox
        function optUtCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.optUtCheckbox;

            % hObject    handle to optUtCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of optUtCheckbox
            if(get(hObject,'Value') && not(get(handles.inheritTimeCheckbox,'Value')))
                handles.utLbText.Enable = 'on';
                handles.utUbText.Enable = 'on';
            else
                handles.utLbText.Enable = 'off';
                handles.utUbText.Enable = 'off';
            end
        end

        % Value changed function: orbit1LbText
        function orbit1LbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit1LbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit1LbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit1LbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit1Text
        function orbit1Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit1Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit1Text as text
            %        str2double(get(hObject,'String')) returns contents of orbit1Text as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit1UbText
        function orbit1UbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit1UbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit1UbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit1UbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit2LbText
        function orbit2LbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit2LbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit2LbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit2LbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit2Text
        function orbit2Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit2Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit2Text as text
            %        str2double(get(hObject,'String')) returns contents of orbit2Text as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit2UbText
        function orbit2UbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit2UbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit2UbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit2UbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit3LbText
        function orbit3LbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit3LbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit3LbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit3LbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit3Text
        function orbit3Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit3Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit3Text as text
            %        str2double(get(hObject,'String')) returns contents of orbit3Text as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit3UbText
        function orbit3UbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit3UbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit3UbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit3UbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit4LbText
        function orbit4LbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit4LbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit4LbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit4LbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit4Text
        function orbit4Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit4Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit4Text as text
            %        str2double(get(hObject,'String')) returns contents of orbit4Text as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit4UbText
        function orbit4UbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit4UbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit4UbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit4UbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit5LbText
        function orbit5LbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit5LbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit5LbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit5LbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit5Text
        function orbit5Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit5Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit5Text as text
            %        str2double(get(hObject,'String')) returns contents of orbit5Text as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit5UbText
        function orbit5UbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit5UbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit5UbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit5UbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit6LbText
        function orbit6LbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit6LbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit6LbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit6LbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit6Text
        function orbit6Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit6Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit6Text as text
            %        str2double(get(hObject,'String')) returns contents of orbit6Text as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit6UbText
        function orbit6UbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit6UbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit6UbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit6UbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Menu selected function: pasteOrbitFromClipboardMenu
        function pasteOrbitFromClipboardMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pasteOrbitFromClipboardMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            contents = cellstr(get(handles.refFrameTypeCombo,'String'));
            selFrameType = contents{get(handles.refFrameTypeCombo,'Value')};
            enum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);
            if(not(enum == ReferenceFrameEnum.BodyCenteredInertial))
                enum = ReferenceFrameEnum.BodyCenteredInertial;
                ind = ReferenceFrameEnum.getIndForName(enum.name);
                handles.refFrameTypeCombo.Value = ind;

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                refFrameTypeCombo_Callback(app, evt);
            end

            contents = cellstr(get(handles.elementSetCombo,'String'));
            selOrbitType = contents{get(handles.elementSetCombo,'Value')};
            [~,enum] = ElementSetEnum.getIndForName(selOrbitType);
            if(not(enum == ElementSetEnum.KeplerianElements))
                enum = ElementSetEnum.KeplerianElements;
                ind = ElementSetEnum.getIndForName(enum.name);
                handles.elementSetCombo.Value = ind;

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                elementSetCombo_Callback(app, evt);
            end

            lvdData = getappdata(handles.lvd_EditActionSetKinematicStateGUI,'lvdData');
            celBodyData = lvdData.celBodyData;

            bodyInfo = pasteOrbitFromClipboard(handles.utText, handles.orbit1Text, handles.orbit2Text, ...
                handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, ...
                handles.orbit6Text, true, [], celBodyData);

            if(not(isempty(bodyInfo)))
                curElemSet = getappdata(handles.lvd_EditActionSetKinematicStateGUI,'curElemSet');
                curElemSet.frame.setOriginBody(bodyInfo);
                updateStateDueToFrameChange(app, handles, AbstractReferenceFrame.empty(1,0));
            end
        end

        % Value changed function: refFrameTypeCombo
        function refFrameTypeCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to refFrameTypeCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns refFrameTypeCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from refFrameTypeCombo
            updateStateDueToFrameChange(app, handles, AbstractReferenceFrame.empty(1,0));
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                app.saveActionAndClose();
            else
                msgbox(errMsg,'Errors were found while editing the state.','error');
            end
        end

        % Button pushed function: setFrameOptionsButton
        function setFrameOptionsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to setFrameOptionsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            curElemSet = getappdata(handles.lvd_EditActionSetKinematicStateGUI,'curElemSet');
            frame = curElemSet.frame;
            newFrame = frame.editFrameDialogUI(EditReferenceFrameContextEnum.ForState);

            updateStateDueToFrameChange(app, handles, newFrame);
        end

        % Value changed function: utLbText
        function utLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to utLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of utLbText as text
            %        str2double(get(hObject,'String')) returns contents of utLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: utText
        function utText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.utText;

            % hObject    handle to utText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of utText as text
            %        str2double(get(hObject,'String')) returns contents of utText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: utUbText
        function utUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to utUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of utUbText as text
            %        str2double(get(hObject,'String')) returns contents of utUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Callback function
        function inheritTimeButtonGroupSelectionChanged(app, event)

        end

        % Close request function: lvd_EditActionSetKinematicStateGUI
        function lvd_EditActionSetKinematicStateGUICloseRequest(app, event)
            delete(app);
        end

        % Value changed function: inheritStageStateCheckbox
        function inheritStageStateCheckboxValueChanged(app, event)
            stageStateData = app.StagesDryMassListbox.Value;
            stageStateData.inheritStageState = event.Value;

            app.setStagesTabEnableDisable();
        end

        % Value changed function: inheritEngineStateCheckbox
        function inheritEngineStateCheckboxValueChanged(app, event)
            engineStateData = app.EnginesListBox.Value;
            engineStateData.inheritEngineState = event.Value;

            app.setEnginesTabEnableDisable();
        end

        % Value changed function: EnginesListBox
        function EnginesListBoxValueChanged(app, event)
            app.populateEngineStatesTab();
        end

        % Value changed function: StagesDryMassListbox
        function StagesDryMassListboxValueChanged(app, event)
            app.populateStageStatesTab();
        end

        % Value changed function: inheritTankMassCheckbox
        function inheritTankMassCheckboxValueChanged(app, event)
            tankStateData = app.TanksListBox.Value;
            tankStateData.inheritTankState = event.Value;

            app.setTanksTabEnableDisable();
        end

        % Selection changed function: inheritStageStateButtonGroup
        function inheritStageStateButtonGroupSelectionChanged(app, event)
            stageStateData = app.StagesDryMassListbox.Value;

            selectedButton = app.inheritStageStateButtonGroup.SelectedObject;
            if(selectedButton == app.inheritStageStateFromPrevStateRadioBtn)
                stageStateData.inheritStageStateFrom = InheritStateEnum.InheritFromLastState;
            elseif(selectedButton == app.inheritStageStateFromSpecEvtRadioBtn)
                stageStateData.inheritStageStateFrom = InheritStateEnum.InheritFromSpecifiedEvent;
            end
        end

        % Value changed function: inheritStageStateSpecEvtCombo
        function inheritStageStateSpecEvtComboValueChanged(app, event)
            stageStateData = app.StagesDryMassListbox.Value;
            stageStateData.inheritStageStateFromEvent = app.inheritStageStateSpecEvtCombo.Value;
        end

        % Value changed function: stageActiveCheckbox
        function stageActiveCheckboxValueChanged(app, event)
            stageStateData = app.StagesDryMassListbox.Value;
            stageStateData.stageStateToSet = app.stageActiveCheckbox.Value;
        end

        % Selection changed function: inheritEngineStateButtonGroup
        function inheritEngineStateButtonGroupSelectionChanged(app, event)
            engineStateData = app.EnginesListBox.Value;

            selectedButton = app.inheritEngineStateButtonGroup.SelectedObject;
            if(selectedButton == app.inheritEngineStateFromPrevStateRadioBtn)
                engineStateData.inheritEngineStateFrom = InheritStateEnum.InheritFromLastState;
            elseif(selectedButton == app.inheritEngineStateFromSpecEvtRadioBtn)
                engineStateData.inheritEngineStateFrom = InheritStateEnum.InheritFromSpecifiedEvent;
            end
        end

        % Value changed function: inheritEngineStateSpecEvtCombo
        function inheritEngineStateSpecEvtComboValueChanged(app, event)
            engineStateData = app.EnginesListBox.Value;
            engineStateData.inheritEngineStateFromEvent = app.inheritEngineStateSpecEvtCombo.Value;
        end

        % Value changed function: engineActiveCheckbox
        function engineActiveCheckboxValueChanged(app, event)
            engineStateData = app.EnginesListBox.Value;
            engineStateData.engineStateToSet = app.engineActiveCheckbox.Value;
        end

        % Selection changed function: inheritTankMassButtonGroup
        function inheritTankMassButtonGroupSelectionChanged(app, event)
            tankStateData = app.TanksListBox.Value;

            selectedButton = app.inheritTankMassButtonGroup.SelectedObject;
            if(selectedButton == app.inheritTankMassFromPrevStateRadioBtn)
                tankStateData.inheritTankStateFrom = InheritStateEnum.InheritFromLastState;
            elseif(selectedButton == app.inheritTankMassFromSpecEvtRadioBtn)
                tankStateData.inheritTankStateFrom = InheritStateEnum.InheritFromSpecifiedEvent;
            end
        end

        % Value changed function: inheritTankMassSpecEvtCombo
        function inheritTankMassSpecEvtComboValueChanged(app, event)
            tankStateData = app.TanksListBox.Value;
            tankStateData.inheritTankStateFromEvent = app.inheritTankMassSpecEvtCombo.Value;
        end

        % Value changed function: TankMassText
        function TankMassTextValueChanged(app, event)
            tankStateData = app.TanksListBox.Value;

            errMsg = validateNumber(str2double(app.TankMassText.Value), 'Tank Mass', 0, Inf, false, {}, app.TankMassText.Value);
            if(isempty(errMsg))
                tankStateData.tankStateMassToSet = str2double(app.TankMassText.Value);
                app.TankMassText.Value = attemptStrEval(app.TankMassText.Value);
            else
                app.TankMassText.Value = event.PreviousValue;
                uialert(app.lvd_EditActionSetKinematicStateGUI, errMsg, 'Input Error', "Icon","error");
            end
        end

        % Value changed function: optTankMassCheckbox
        function optTankMassCheckboxValueChanged(app, event)
            tankStateData = app.TanksListBox.Value;

            if(isempty(tankStateData.optVar))
                var = SetKinematicStateTankStateOptimVar(tankStateData);
            else
                var = tankStateData.optVar;
            end

            var.useTf = app.optTankMassCheckbox.Value;
            app.setTanksTabEnableDisable();
        end

        % Value changed function: tankDryMassLbText
        function tankDryMassLbTextValueChanged(app, event)
            tankStateData = app.TanksListBox.Value;

            if(isempty(tankStateData.optVar))
                var = SetKinematicStateTankStateOptimVar(tankStateData);
            else
                var = tankStateData.optVar;
            end

            errMsg = validateNumber(str2double(app.tankDryMassLbText.Value), 'Tank Mass Lower Bound', 0, Inf, false, {}, app.tankDryMassLbText.Value);
            if(isempty(errMsg))
                var.lwrBnd = str2double(app.tankDryMassLbText.Value);
                app.tankDryMassLbText.Value = attemptStrEval(app.tankDryMassLbText.Value);
            else
                app.tankDryMassLbText.Value = event.PreviousValue;
                uialert(app.lvd_EditActionSetKinematicStateGUI, errMsg, 'Input Error', "Icon","error");
            end
        end

        % Value changed function: tankDryMassUbText
        function tankDryMassUbTextValueChanged(app, event)
            tankStateData = app.TanksListBox.Value;

            if(isempty(tankStateData.optVar))
                var = SetKinematicStateTankStateOptimVar(tankStateData);
            else
                var = tankStateData.optVar;
            end

            errMsg = validateNumber(str2double(app.tankDryMassUbText.Value), 'Tank Mass Upper Bound', str2double(app.tankDryMassLbText.Value), Inf, false, {}, app.tankDryMassUbText.Value);
            if(isempty(errMsg))
                var.uprBnd = str2double(app.tankDryMassUbText.Value);
                app.tankDryMassUbText.Value = attemptStrEval(app.tankDryMassUbText.Value);
            else
                app.tankDryMassUbText.Value = event.PreviousValue;
                uialert(app.lvd_EditActionSetKinematicStateGUI, errMsg, 'Input Error', "Icon","error");
            end
        end

        % Value changed function: TanksListBox
        function TanksListBoxValueChanged(app, event)
            app.populateTankStatesTab();
        end

        % Value changed function: EpsSrcListbox
        function EpsSrcListboxValueChanged(app, event)
            app.populateEpsSrcStatesTab();
        end

        % Value changed function: EpsSinkListbox
        function EpsSinkListboxValueChanged(app, event)
            app.populateEpsSinkStatesTab();
        end

        % Value changed function: EpsStorageListbox
        function EpsStorageListboxValueChanged(app, event)
            app.populateEpsStorageStatesTab();
        end

        % Value changed function: StorageSoCText
        function StorageSoCTextValueChanged(app, event)
            epsStorageStateData = app.EpsStorageListbox.Value;

            errMsg = validateNumber(str2double(app.StorageSoCText.Value), 'State of Charge', 0, Inf, false, {}, app.StorageSoCText.Value);
            if(isempty(errMsg))
                epsStorageStateData.socToSet = str2double(app.StorageSoCText.Value);
                app.StorageSoCText.Value = attemptStrEval(app.StorageSoCText.Value);
            else
                app.StorageSoCText.Value = event.PreviousValue;
                uialert(app.lvd_EditActionSetKinematicStateGUI, errMsg, 'Input Error', "Icon","error");
            end
        end

        % Value changed function: optStorageSoCCheckbox
        function optStorageSoCCheckboxValueChanged(app, event)
            epsStorageStateData = app.EpsStorageListbox.Value;

            if(isempty(epsStorageStateData.optVar))
                var = SetKinematicStateEpsStorageStateOptimVar(epsStorageStateData);
            else
                var = epsStorageStateData.optVar;
            end

            var.useTf = app.optStorageSoCCheckbox.Value;
            app.setEpsStoreTabEnableDisable();
        end

        % Value changed function: storageSoCLbText
        function storageSoCLbTextValueChanged(app, event)
            epsStorageStateData = app.EpsStorageListbox.Value;

            if(isempty(epsStorageStateData.optVar))
                var = SetKinematicStateEpsStorageStateOptimVar(epsStorageStateData);
            else
                var = epsStorageStateData.optVar;
            end

            errMsg = validateNumber(str2double(app.storageSoCLbText.Value), 'State of Charge Lower Bound', 0, Inf, false, {}, app.storageSoCLbText.Value);
            if(isempty(errMsg))
                var.lwrBnd = str2double(app.storageSoCLbText.Value);
                app.storageSoCLbText.Value = attemptStrEval(app.storageSoCLbText.Value);
            else
                app.storageSoCLbText.Value = event.PreviousValue;
                uialert(app.lvd_EditActionSetKinematicStateGUI, errMsg, 'Input Error', "Icon","error");
            end
        end

        % Value changed function: storageSoCUbText
        function storageSoCUbTextValueChanged(app, event)
            epsStorageStateData = app.EpsStorageListbox.Value;

            if(isempty(epsStorageStateData.optVar))
                var = SetKinematicStateEpsStorageStateOptimVar(epsStorageStateData);
            else
                var = epsStorageStateData.optVar;
            end

            errMsg = validateNumber(str2double(app.storageSoCUbText.Value), 'State of Charge Upper Bound', str2double(app.tankDryMassLbText.Value), Inf, false, {}, app.storageSoCUbText.Value);
            if(isempty(errMsg))
                var.uprBnd = str2double(app.storageSoCUbText.Value);
                app.storageSoCUbText.Value = attemptStrEval(app.storageSoCUbText.Value);
            else
                app.storageSoCUbText.Value = event.PreviousValue;
                uialert(app.lvd_EditActionSetKinematicStateGUI, errMsg, 'Input Error', "Icon","error");
            end
        end

        % Value changed function: inheritEpsStorageStateCheckbox
        function inheritEpsStorageStateCheckboxValueChanged(app, event)
            epsStorageStateData = app.EpsStorageListbox.Value;
            epsStorageStateData.inheritStorageState = event.Value;

            app.setEpsStoreTabEnableDisable();
        end

        % Value changed function: inheritEpsSrcStateCheckbox
        function inheritEpsSrcStateCheckboxValueChanged(app, event)
            epsSrcStateData = app.EpsSrcListbox.Value;
            epsSrcStateData.inheritSrcState = event.Value;

            app.setEpsSrcTabEnableDisable();
        end

        % Value changed function: inheritEpsSinkStateCheckbox
        function inheritEpsSinkStateCheckboxValueChanged(app, event)
            epsSinkStateData = app.EpsSinkListbox.Value;
            epsSinkStateData.inheritSinkState = event.Value;

            app.setEpsSinkTabEnableDisable();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditActionSetKinematicStateGUI = uifigure('Visible', 'off');
            app.lvd_EditActionSetKinematicStateGUI.Name = 'Edit Action';
            app.lvd_EditActionSetKinematicStateGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditActionSetKinematicStateGUI.CloseRequestFcn = createCallbackFcn(app, @lvd_EditActionSetKinematicStateGUICloseRequest, true);
            app.lvd_EditActionSetKinematicStateGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditActionSetKinematicStateGUI_WindowKeyPressFcn, true);
            app.lvd_EditActionSetKinematicStateGUI.Tag = 'lvd_EditActionSetKinematicStateGUI';
            app.GridLayout = uigridlayout(app.lvd_EditActionSetKinematicStateGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Action';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Text = 'Cancel';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TimeTab = uitab(app.TabGroup);
            app.TimeTab.Title = 'Time';
            app.GridLayout3 = uigridlayout(app.TimeTab);
            app.inheritTimeCheckbox = uicheckbox(app.GridLayout3);
            app.inheritTimeCheckbox.ValueChangedFcn = createCallbackFcn(app, @inheritTimeCheckbox_Callback, true);
            app.inheritTimeCheckbox.Tag = 'inheritTimeCheckbox';
            app.inheritTimeCheckbox.Tooltip = 'Inheriting time will keep time continous from the end of the event to this action.';
            app.inheritTimeCheckbox.Text = 'Inherit Time';
            app.SpecifyTimePanel = uipanel(app.GridLayout3);
            app.SpecifyTimePanel.Title = 'Specify Time';
            app.GridLayout4 = uigridlayout(app.SpecifyTimePanel);
            app.text6 = uilabel(app.GridLayout4);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Epoch (UT)';
            app.utText = uieditfield(app.GridLayout4, 'text');
            app.utText.ValueChangedFcn = createCallbackFcn(app, @utText_Callback, true);
            app.utText.Tag = 'utText';
            app.utText.Value = '0.0';
            app.utUnitLabel = uilabel(app.GridLayout4);
            app.utUnitLabel.Tag = 'utUnitLabel';
            app.utUnitLabel.Text = 'sec';
            app.optUtCheckbox = uicheckbox(app.GridLayout4);
            app.optUtCheckbox.ValueChangedFcn = createCallbackFcn(app, @optUtCheckbox_Callback, true);
            app.optUtCheckbox.Tag = 'optUtCheckbox';
            app.optUtCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.optUtCheckbox.Text = 'Opt?';
            app.optUtCheckbox.Value = true;
            app.utLbText = uieditfield(app.GridLayout4, 'text');
            app.utLbText.ValueChangedFcn = createCallbackFcn(app, @utLbText_Callback, true);
            app.utLbText.Tag = 'utLbText';
            app.utLbText.Tooltip = 'Parameter lower bound.';
            app.utLbText.Value = '0.0';
            app.utUbText = uieditfield(app.GridLayout4, 'text');
            app.utUbText.ValueChangedFcn = createCallbackFcn(app, @utUbText_Callback, true);
            app.utUbText.Tag = 'utUbText';
            app.utUbText.Tooltip = 'Parameter upper bound.';
            app.utUbText.Value = '0.0';
            app.inheritTimeButtonGroup = uibuttongroup(app.GridLayout3);
            app.inheritTimeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @inheritTimeButtonGroup_SelectionChangedFcn, true);
            app.inheritTimeButtonGroup.Title = 'Inherit From';
            app.inheritTimeButtonGroup.Tag = 'inheritTimeButtonGroup';
            app.inheritTimeFromPrevStateRadioBtn = uiradiobutton(app.inheritTimeButtonGroup);
            app.inheritTimeFromPrevStateRadioBtn.Tag = 'inheritTimeFromPrevStateRadioBtn';
            app.inheritTimeFromPrevStateRadioBtn.Tooltip = 'Selecting this will inherit the time from the spacecraft state immediately prior to executing this action.';
            app.inheritTimeFromPrevStateRadioBtn.Text = 'Previous State';
            app.inheritTimeFromPrevStateRadioBtn.Value = true;
            app.inheritTimeFromSpecEvtRadioBtn = uiradiobutton(app.inheritTimeButtonGroup);
            app.inheritTimeFromSpecEvtRadioBtn.Tag = 'inheritTimeFromSpecEvtRadioBtn';
            app.inheritTimeFromSpecEvtRadioBtn.Tooltip = 'Selecting this will inherit the time from the spacecraft state at the end of the specified event.  Note that the specified event MUST OCCUR prior to the event this action is executed at.';
            app.inheritTimeFromSpecEvtRadioBtn.Text = 'Specified Event:';
            app.inheritTimeSpecEvtCombo = uidropdown(app.inheritTimeButtonGroup);
            app.inheritTimeSpecEvtCombo.Items = {'Pop-up Menu'};
            app.inheritTimeSpecEvtCombo.Tag = 'inheritTimeSpecEvtCombo';
            app.inheritTimeSpecEvtCombo.Tooltip = 'The time will be inherited from the final state of this event.  MUST BE an event which is executed prior to this event.';
            app.inheritTimeSpecEvtCombo.Value = 'Pop-up Menu';
            app.OrbitStateTab = uitab(app.TabGroup);
            app.OrbitStateTab.Title = 'Orbit State';
            app.GridLayout5 = uigridlayout(app.OrbitStateTab);
            app.OrbitalParametersPanel = uipanel(app.GridLayout5);
            app.OrbitalParametersPanel.Title = 'Orbital Parameters';
            app.GridLayout6 = uigridlayout(app.OrbitalParametersPanel);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.text3 = uilabel(app.GridLayout7);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Element Set';
            app.elementSetCombo = uidropdown(app.GridLayout7);
            app.elementSetCombo.Items = {'Pop-up Menu'};
            app.elementSetCombo.ValueChangedFcn = createCallbackFcn(app, @elementSetCombo_Callback, true);
            app.elementSetCombo.Tag = 'elementSetCombo';
            app.elementSetCombo.Tooltip = 'The elements used to define the state (position and velocity) of the vehicle.';
            app.elementSetCombo.Value = 'Pop-up Menu';
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.text5 = uilabel(app.GridLayout8);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Frame Type';
            app.refFrameTypeCombo = uidropdown(app.GridLayout8);
            app.refFrameTypeCombo.Items = {'Pop-up Menu'};
            app.refFrameTypeCombo.ValueChangedFcn = createCallbackFcn(app, @refFrameTypeCombo_Callback, true);
            app.refFrameTypeCombo.Tag = 'refFrameTypeCombo';
            app.refFrameTypeCombo.Tooltip = 'The type of reference frame used with the selected element set, relative to the selected central body.  Generally, use Keplerian elements with an inertial frame and geographic elements with a body-fixed frame.';
            app.refFrameTypeCombo.Value = 'Pop-up Menu';
            app.setFrameOptionsButton = uibutton(app.GridLayout8, 'push');
            app.setFrameOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @setFrameOptionsButton_Callback, true);
            app.setFrameOptionsButton.Tag = 'setFrameOptionsButton';
            app.setFrameOptionsButton.Text = 'Set Frame Options';
            app.StatePanel = uipanel(app.GridLayout5);
            app.StatePanel.Title = 'State';
            app.GridLayout9 = uigridlayout(app.StatePanel);
            app.inheritStateCheckbox = uicheckbox(app.GridLayout9);
            app.inheritStateCheckbox.ValueChangedFcn = createCallbackFcn(app, @inheritStateCheckbox_Callback, true);
            app.inheritStateCheckbox.Tag = 'inheritStateCheckbox';
            app.inheritStateCheckbox.Tooltip = 'Inheriting state will keep position and velocity (or their other 6 element equivalents) continous from the end of the event to this action.';
            app.inheritStateCheckbox.Text = 'Inherit State';
            app.inheritStateButtonGroup = uibuttongroup(app.GridLayout9);
            app.inheritStateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @inheritStateButtonGroup_SelectionChangedFcn, true);
            app.inheritStateButtonGroup.Title = 'Inherit From';
            app.inheritStateButtonGroup.Tag = 'inheritStateButtonGroup';
            app.inheritStateFromPrevStateRadioBtn = uiradiobutton(app.inheritStateButtonGroup);
            app.inheritStateFromPrevStateRadioBtn.Tag = 'inheritStateFromPrevStateRadioBtn';
            app.inheritStateFromPrevStateRadioBtn.Tooltip = 'Selecting this will inherit the state (position, velocity) from the spacecraft state immediately prior to executing this action.';
            app.inheritStateFromPrevStateRadioBtn.Text = 'Previous State';
            app.inheritStateFromPrevStateRadioBtn.Value = true;
            app.inheritStateFromSpecEvtRadioBtn = uiradiobutton(app.inheritStateButtonGroup);
            app.inheritStateFromSpecEvtRadioBtn.Tag = 'inheritStateFromSpecEvtRadioBtn';
            app.inheritStateFromSpecEvtRadioBtn.Tooltip = 'Selecting this will inherit the state (position, velocity) from the spacecraft state at the end of the specified event.  Note that the specified event MUST OCCUR prior to the event this action is executed at.';
            app.inheritStateFromSpecEvtRadioBtn.Text = 'Specified Event:';
            app.inheritStateSpecEvtCombo = uidropdown(app.inheritStateButtonGroup);
            app.inheritStateSpecEvtCombo.Items = {'Pop-up Menu'};
            app.inheritStateSpecEvtCombo.Tag = 'inheritStateSpecEvtCombo';
            app.inheritStateSpecEvtCombo.Tooltip = 'The spacecraft state (position, velocity) will be inherited from the final state of this event.  MUST BE an event which is executed prior to this event.';
            app.inheritStateSpecEvtCombo.Value = 'Pop-up Menu';
            app.SpecifyStatePanel = uipanel(app.GridLayout9);
            app.SpecifyStatePanel.Title = 'Specify State';
            app.GridLayout10 = uigridlayout(app.SpecifyStatePanel);
            app.orbit1Label = uilabel(app.GridLayout10);
            app.orbit1Label.Tag = 'orbit1Label';
            app.orbit1Label.Text = 'Orbit1';
            app.orbit2Label = uilabel(app.GridLayout10);
            app.orbit2Label.Tag = 'orbit2Label';
            app.orbit2Label.Text = 'Orbit2';
            app.orbit3Label = uilabel(app.GridLayout10);
            app.orbit3Label.Tag = 'orbit3Label';
            app.orbit3Label.Text = 'Orbit3';
            app.orbit4Label = uilabel(app.GridLayout10);
            app.orbit4Label.Tag = 'orbit4Label';
            app.orbit4Label.Text = 'Orbit4';
            app.orbit5Label = uilabel(app.GridLayout10);
            app.orbit5Label.Tag = 'orbit5Label';
            app.orbit5Label.Text = 'Orbit5';
            app.orbit6Label = uilabel(app.GridLayout10);
            app.orbit6Label.Tag = 'orbit6Label';
            app.orbit6Label.Text = 'Orbit6';
            app.orbit1Text = uieditfield(app.GridLayout10, 'text');
            app.orbit1Text.ValueChangedFcn = createCallbackFcn(app, @orbit1Text_Callback, true);
            app.orbit1Text.Tag = 'orbit1Text';
            app.orbit1Text.Value = '0.0';
            app.orbit2Text = uieditfield(app.GridLayout10, 'text');
            app.orbit2Text.ValueChangedFcn = createCallbackFcn(app, @orbit2Text_Callback, true);
            app.orbit2Text.Tag = 'orbit2Text';
            app.orbit2Text.Value = '0.0';
            app.orbit3Text = uieditfield(app.GridLayout10, 'text');
            app.orbit3Text.ValueChangedFcn = createCallbackFcn(app, @orbit3Text_Callback, true);
            app.orbit3Text.Tag = 'orbit3Text';
            app.orbit3Text.Value = '0.0';
            app.orbit4Text = uieditfield(app.GridLayout10, 'text');
            app.orbit4Text.ValueChangedFcn = createCallbackFcn(app, @orbit4Text_Callback, true);
            app.orbit4Text.Tag = 'orbit4Text';
            app.orbit4Text.Value = '0.0';
            app.orbit5Text = uieditfield(app.GridLayout10, 'text');
            app.orbit5Text.ValueChangedFcn = createCallbackFcn(app, @orbit5Text_Callback, true);
            app.orbit5Text.Tag = 'orbit5Text';
            app.orbit5Text.Value = '0.0';
            app.orbit6Text = uieditfield(app.GridLayout10, 'text');
            app.orbit6Text.ValueChangedFcn = createCallbackFcn(app, @orbit6Text_Callback, true);
            app.orbit6Text.Tag = 'orbit6Text';
            app.orbit6Text.Value = '0.0';
            app.orbit1UnitLabel = uilabel(app.GridLayout10);
            app.orbit1UnitLabel.Tag = 'orbit1UnitLabel';
            app.orbit1UnitLabel.Text = '';
            app.orbit2UnitLabel = uilabel(app.GridLayout10);
            app.orbit2UnitLabel.Tag = 'orbit2UnitLabel';
            app.orbit2UnitLabel.Text = '';
            app.orbit3UnitLabel = uilabel(app.GridLayout10);
            app.orbit3UnitLabel.Tag = 'orbit3UnitLabel';
            app.orbit3UnitLabel.Text = '';
            app.orbit4UnitLabel = uilabel(app.GridLayout10);
            app.orbit4UnitLabel.Tag = 'orbit4UnitLabel';
            app.orbit4UnitLabel.Text = '';
            app.orbit5UnitLabel = uilabel(app.GridLayout10);
            app.orbit5UnitLabel.Tag = 'orbit5UnitLabel';
            app.orbit5UnitLabel.Text = '';
            app.orbit6UnitLabel = uilabel(app.GridLayout10);
            app.orbit6UnitLabel.Tag = 'orbit6UnitLabel';
            app.orbit6UnitLabel.Text = '';
            app.optOrbit1Checkbox = uicheckbox(app.GridLayout10);
            app.optOrbit1Checkbox.ValueChangedFcn = createCallbackFcn(app, @optOrbit1Checkbox_Callback, true);
            app.optOrbit1Checkbox.Tag = 'optOrbit1Checkbox';
            app.optOrbit1Checkbox.Tooltip = 'Select to optimize this quantity.';
            app.optOrbit1Checkbox.Text = 'Opt?';
            app.optOrbit1Checkbox.Value = true;
            app.optOrbit2Checkbox = uicheckbox(app.GridLayout10);
            app.optOrbit2Checkbox.ValueChangedFcn = createCallbackFcn(app, @optOrbit2Checkbox_Callback, true);
            app.optOrbit2Checkbox.Tag = 'optOrbit2Checkbox';
            app.optOrbit2Checkbox.Tooltip = 'Select to optimize this quantity.';
            app.optOrbit2Checkbox.Text = 'Opt?';
            app.optOrbit2Checkbox.Value = true;
            app.optOrbit3Checkbox = uicheckbox(app.GridLayout10);
            app.optOrbit3Checkbox.ValueChangedFcn = createCallbackFcn(app, @optOrbit3Checkbox_Callback, true);
            app.optOrbit3Checkbox.Tag = 'optOrbit3Checkbox';
            app.optOrbit3Checkbox.Tooltip = 'Select to optimize this quantity.';
            app.optOrbit3Checkbox.Text = 'Opt?';
            app.optOrbit3Checkbox.Value = true;
            app.optOrbit4Checkbox = uicheckbox(app.GridLayout10);
            app.optOrbit4Checkbox.ValueChangedFcn = createCallbackFcn(app, @optOrbit4Checkbox_Callback, true);
            app.optOrbit4Checkbox.Tag = 'optOrbit4Checkbox';
            app.optOrbit4Checkbox.Tooltip = 'Select to optimize this quantity.';
            app.optOrbit4Checkbox.Text = 'Opt?';
            app.optOrbit4Checkbox.Value = true;
            app.optOrbit5Checkbox = uicheckbox(app.GridLayout10);
            app.optOrbit5Checkbox.ValueChangedFcn = createCallbackFcn(app, @optOrbit5Checkbox_Callback, true);
            app.optOrbit5Checkbox.Tag = 'optOrbit5Checkbox';
            app.optOrbit5Checkbox.Tooltip = 'Select to optimize this quantity.';
            app.optOrbit5Checkbox.Text = 'Opt?';
            app.optOrbit5Checkbox.Value = true;
            app.optOrbit6Checkbox = uicheckbox(app.GridLayout10);
            app.optOrbit6Checkbox.ValueChangedFcn = createCallbackFcn(app, @optOrbit6Checkbox_Callback, true);
            app.optOrbit6Checkbox.Tag = 'optOrbit6Checkbox';
            app.optOrbit6Checkbox.Tooltip = 'Select to optimize this quantity.';
            app.optOrbit6Checkbox.Text = 'Opt?';
            app.optOrbit6Checkbox.Value = true;
            app.orbit1LbText = uieditfield(app.GridLayout10, 'text');
            app.orbit1LbText.ValueChangedFcn = createCallbackFcn(app, @orbit1LbText_Callback, true);
            app.orbit1LbText.Tag = 'orbit1LbText';
            app.orbit1LbText.Tooltip = 'Parameter lower bound.';
            app.orbit1LbText.Value = '0.0';
            app.orbit2LbText = uieditfield(app.GridLayout10, 'text');
            app.orbit2LbText.ValueChangedFcn = createCallbackFcn(app, @orbit2LbText_Callback, true);
            app.orbit2LbText.Tag = 'orbit2LbText';
            app.orbit2LbText.Tooltip = 'Parameter lower bound.';
            app.orbit2LbText.Value = '0.0';
            app.orbit3LbText = uieditfield(app.GridLayout10, 'text');
            app.orbit3LbText.ValueChangedFcn = createCallbackFcn(app, @orbit3LbText_Callback, true);
            app.orbit3LbText.Tag = 'orbit3LbText';
            app.orbit3LbText.Tooltip = 'Parameter lower bound.';
            app.orbit3LbText.Value = '0.0';
            app.orbit4LbText = uieditfield(app.GridLayout10, 'text');
            app.orbit4LbText.ValueChangedFcn = createCallbackFcn(app, @orbit4LbText_Callback, true);
            app.orbit4LbText.Tag = 'orbit4LbText';
            app.orbit4LbText.Tooltip = 'Parameter lower bound.';
            app.orbit4LbText.Value = '0.0';
            app.orbit5LbText = uieditfield(app.GridLayout10, 'text');
            app.orbit5LbText.ValueChangedFcn = createCallbackFcn(app, @orbit5LbText_Callback, true);
            app.orbit5LbText.Tag = 'orbit5LbText';
            app.orbit5LbText.Tooltip = 'Parameter lower bound.';
            app.orbit5LbText.Value = '0.0';
            app.orbit6LbText = uieditfield(app.GridLayout10, 'text');
            app.orbit6LbText.ValueChangedFcn = createCallbackFcn(app, @orbit6LbText_Callback, true);
            app.orbit6LbText.Tag = 'orbit6LbText';
            app.orbit6LbText.Tooltip = 'Parameter lower bound.';
            app.orbit6LbText.Value = '0.0';
            app.orbit1UbText = uieditfield(app.GridLayout10, 'text');
            app.orbit1UbText.ValueChangedFcn = createCallbackFcn(app, @orbit1UbText_Callback, true);
            app.orbit1UbText.Tag = 'orbit1UbText';
            app.orbit1UbText.Tooltip = 'Parameter upper bound.';
            app.orbit1UbText.Value = '0.0';
            app.orbit2UbText = uieditfield(app.GridLayout10, 'text');
            app.orbit2UbText.ValueChangedFcn = createCallbackFcn(app, @orbit2UbText_Callback, true);
            app.orbit2UbText.Tag = 'orbit2UbText';
            app.orbit2UbText.Tooltip = 'Parameter upper bound.';
            app.orbit2UbText.Value = '0.0';
            app.orbit3UbText = uieditfield(app.GridLayout10, 'text');
            app.orbit3UbText.ValueChangedFcn = createCallbackFcn(app, @orbit3UbText_Callback, true);
            app.orbit3UbText.Tag = 'orbit3UbText';
            app.orbit3UbText.Tooltip = 'Parameter upper bound.';
            app.orbit3UbText.Value = '0.0';
            app.orbit4UbText = uieditfield(app.GridLayout10, 'text');
            app.orbit4UbText.ValueChangedFcn = createCallbackFcn(app, @orbit4UbText_Callback, true);
            app.orbit4UbText.Tag = 'orbit4UbText';
            app.orbit4UbText.Tooltip = 'Parameter upper bound.';
            app.orbit4UbText.Value = '0.0';
            app.orbit5UbText = uieditfield(app.GridLayout10, 'text');
            app.orbit5UbText.ValueChangedFcn = createCallbackFcn(app, @orbit5UbText_Callback, true);
            app.orbit5UbText.Tag = 'orbit5UbText';
            app.orbit5UbText.Tooltip = 'Parameter upper bound.';
            app.orbit5UbText.Value = '0.0';
            app.orbit6UbText = uieditfield(app.GridLayout10, 'text');
            app.orbit6UbText.ValueChangedFcn = createCallbackFcn(app, @orbit6UbText_Callback, true);
            app.orbit6UbText.Tag = 'orbit6UbText';
            app.orbit6UbText.Tooltip = 'Parameter upper bound.';
            app.orbit6UbText.Value = '0.0';
            app.ComponentStatesTab = uitab(app.TabGroup);
            app.ComponentStatesTab.Title = 'Component States';
            app.GridLayout14 = uigridlayout(app.ComponentStatesTab);
            app.TabGroup2 = uitabgroup(app.GridLayout14);
            app.StageStatesTab_2 = uitab(app.TabGroup2);
            app.StageStatesTab_2.Title = 'Stage States';
            app.GridLayout15 = uigridlayout(app.StageStatesTab_2);
            app.StagesDryMassListbox = uilistbox(app.GridLayout15);
            app.StagesDryMassListbox.ValueChangedFcn = createCallbackFcn(app, @StagesDryMassListboxValueChanged, true);
            app.GridLayout18 = uigridlayout(app.GridLayout15);
            app.inheritStageStateCheckbox = uicheckbox(app.GridLayout18);
            app.inheritStageStateCheckbox.ValueChangedFcn = createCallbackFcn(app, @inheritStageStateCheckboxValueChanged, true);
            app.inheritStageStateCheckbox.Tag = 'inheritStageStateCheckbox';
            app.inheritStageStateCheckbox.Text = 'Inherit Stage States';
            app.inheritStageStateCheckbox.Value = true;
            app.inheritStageStateButtonGroup = uibuttongroup(app.GridLayout18);
            app.inheritStageStateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @inheritStageStateButtonGroupSelectionChanged, true);
            app.inheritStageStateButtonGroup.Title = 'Inherit From';
            app.inheritStageStateButtonGroup.Tag = 'inheritStageStateButtonGroup';
            app.inheritStageStateFromPrevStateRadioBtn = uiradiobutton(app.inheritStageStateButtonGroup);
            app.inheritStageStateFromPrevStateRadioBtn.Tag = 'inheritStageStateFromPrevStateRadioBtn';
            app.inheritStageStateFromPrevStateRadioBtn.Tooltip = 'Selecting this will inherit the stage states (masses, etc) from the spacecraft state immediately prior to executing this action.';
            app.inheritStageStateFromPrevStateRadioBtn.Text = 'Previous State';
            app.inheritStageStateFromPrevStateRadioBtn.Value = true;
            app.inheritStageStateFromSpecEvtRadioBtn = uiradiobutton(app.inheritStageStateButtonGroup);
            app.inheritStageStateFromSpecEvtRadioBtn.Tag = 'inheritStageStateFromSpecEvtRadioBtn';
            app.inheritStageStateFromSpecEvtRadioBtn.Tooltip = 'Selecting this will inherit the stage states (masses, etc) from the spacecraft state at the end of the specified event.  Note that the specified event MUST OCCUR prior to the event this action is executed at.';
            app.inheritStageStateFromSpecEvtRadioBtn.Text = 'Specified Event:';
            app.inheritStageStateSpecEvtCombo = uidropdown(app.inheritStageStateButtonGroup);
            app.inheritStageStateSpecEvtCombo.Items = {'Pop-up Menu'};
            app.inheritStageStateSpecEvtCombo.ValueChangedFcn = createCallbackFcn(app, @inheritStageStateSpecEvtComboValueChanged, true);
            app.inheritStageStateSpecEvtCombo.Tag = 'inheritStageStateSpecEvtCombo';
            app.inheritStageStateSpecEvtCombo.Tooltip = 'The spacecraft stage states (masses, etc) will be inherited from the final state of this event.  MUST BE an event which is executed prior to this event.';
            app.inheritStageStateSpecEvtCombo.Value = 'Pop-up Menu';
            app.StageStatePanel = uipanel(app.GridLayout18);
            app.StageStatePanel.Title = 'Stage State';
            app.GridLayout21 = uigridlayout(app.StageStatePanel);
            app.stageActiveCheckbox = uicheckbox(app.GridLayout21);
            app.stageActiveCheckbox.ValueChangedFcn = createCallbackFcn(app, @stageActiveCheckboxValueChanged, true);
            app.stageActiveCheckbox.Tag = 'stageActiveCheckbox';
            app.stageActiveCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.stageActiveCheckbox.Text = 'Stage is Active?';
            app.stageActiveCheckbox.Value = true;
            app.EngineStatesTab = uitab(app.TabGroup2);
            app.EngineStatesTab.Title = 'Engine States';
            app.GridLayout16 = uigridlayout(app.EngineStatesTab);
            app.EnginesListBox = uilistbox(app.GridLayout16);
            app.EnginesListBox.ValueChangedFcn = createCallbackFcn(app, @EnginesListBoxValueChanged, true);
            app.GridLayout19 = uigridlayout(app.GridLayout16);
            app.inheritEngineStateCheckbox = uicheckbox(app.GridLayout19);
            app.inheritEngineStateCheckbox.ValueChangedFcn = createCallbackFcn(app, @inheritEngineStateCheckboxValueChanged, true);
            app.inheritEngineStateCheckbox.Tag = 'inheritEngineStateCheckbox';
            app.inheritEngineStateCheckbox.Text = 'Inherit Engine State';
            app.inheritEngineStateCheckbox.Value = true;
            app.inheritEngineStateButtonGroup = uibuttongroup(app.GridLayout19);
            app.inheritEngineStateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @inheritEngineStateButtonGroupSelectionChanged, true);
            app.inheritEngineStateButtonGroup.Title = 'Inherit From';
            app.inheritEngineStateButtonGroup.Tag = 'inheritEngineStateButtonGroup';
            app.inheritEngineStateFromPrevStateRadioBtn = uiradiobutton(app.inheritEngineStateButtonGroup);
            app.inheritEngineStateFromPrevStateRadioBtn.Tag = 'inheritEngineStateFromPrevStateRadioBtn';
            app.inheritEngineStateFromPrevStateRadioBtn.Tooltip = 'Selecting this will inherit the stage states (masses, etc) from the spacecraft state immediately prior to executing this action.';
            app.inheritEngineStateFromPrevStateRadioBtn.Text = 'Previous State';
            app.inheritEngineStateFromPrevStateRadioBtn.Value = true;
            app.inheritEngineStateFromSpecEvtRadioBtn = uiradiobutton(app.inheritEngineStateButtonGroup);
            app.inheritEngineStateFromSpecEvtRadioBtn.Tag = 'inheritEngineStateFromSpecEvtRadioBtn';
            app.inheritEngineStateFromSpecEvtRadioBtn.Tooltip = 'Selecting this will inherit the stage states (masses, etc) from the spacecraft state at the end of the specified event.  Note that the specified event MUST OCCUR prior to the event this action is executed at.';
            app.inheritEngineStateFromSpecEvtRadioBtn.Text = 'Specified Event:';
            app.inheritEngineStateSpecEvtCombo = uidropdown(app.inheritEngineStateButtonGroup);
            app.inheritEngineStateSpecEvtCombo.Items = {'Pop-up Menu'};
            app.inheritEngineStateSpecEvtCombo.ValueChangedFcn = createCallbackFcn(app, @inheritEngineStateSpecEvtComboValueChanged, true);
            app.inheritEngineStateSpecEvtCombo.Tag = 'inheritEngineStateSpecEvtCombo';
            app.inheritEngineStateSpecEvtCombo.Tooltip = 'The spacecraft stage states (masses, etc) will be inherited from the final state of this event.  MUST BE an event which is executed prior to this event.';
            app.inheritEngineStateSpecEvtCombo.Value = 'Pop-up Menu';
            app.EngineStatePanel = uipanel(app.GridLayout19);
            app.EngineStatePanel.Title = 'Engine State';
            app.GridLayout21_2 = uigridlayout(app.EngineStatePanel);
            app.engineActiveCheckbox = uicheckbox(app.GridLayout21_2);
            app.engineActiveCheckbox.ValueChangedFcn = createCallbackFcn(app, @engineActiveCheckboxValueChanged, true);
            app.engineActiveCheckbox.Tag = 'engineActiveCheckbox';
            app.engineActiveCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.engineActiveCheckbox.Text = 'Engine is Active?';
            app.engineActiveCheckbox.Value = true;
            app.TankMassesTab = uitab(app.TabGroup2);
            app.TankMassesTab.Title = 'Tank Masses';
            app.GridLayout17 = uigridlayout(app.TankMassesTab);
            app.TanksListBox = uilistbox(app.GridLayout17);
            app.TanksListBox.ValueChangedFcn = createCallbackFcn(app, @TanksListBoxValueChanged, true);
            app.GridLayout20 = uigridlayout(app.GridLayout17);
            app.inheritTankMassCheckbox = uicheckbox(app.GridLayout20);
            app.inheritTankMassCheckbox.ValueChangedFcn = createCallbackFcn(app, @inheritTankMassCheckboxValueChanged, true);
            app.inheritTankMassCheckbox.Tag = 'inheritTankMassCheckbox';
            app.inheritTankMassCheckbox.Text = 'Inherit Tank Mass';
            app.inheritTankMassCheckbox.Value = true;
            app.inheritTankMassButtonGroup = uibuttongroup(app.GridLayout20);
            app.inheritTankMassButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @inheritTankMassButtonGroupSelectionChanged, true);
            app.inheritTankMassButtonGroup.Title = 'Inherit From';
            app.inheritTankMassButtonGroup.Tag = 'inheritTankMassButtonGroup';
            app.inheritTankMassFromPrevStateRadioBtn = uiradiobutton(app.inheritTankMassButtonGroup);
            app.inheritTankMassFromPrevStateRadioBtn.Tag = 'inheritTankMassFromPrevStateRadioBtn';
            app.inheritTankMassFromPrevStateRadioBtn.Tooltip = 'Selecting this will inherit the stage states (masses, etc) from the spacecraft state immediately prior to executing this action.';
            app.inheritTankMassFromPrevStateRadioBtn.Text = 'Previous State';
            app.inheritTankMassFromPrevStateRadioBtn.Value = true;
            app.inheritTankMassFromSpecEvtRadioBtn = uiradiobutton(app.inheritTankMassButtonGroup);
            app.inheritTankMassFromSpecEvtRadioBtn.Tag = 'inheritTankMassFromSpecEvtRadioBtn';
            app.inheritTankMassFromSpecEvtRadioBtn.Tooltip = 'Selecting this will inherit the stage states (masses, etc) from the spacecraft state at the end of the specified event.  Note that the specified event MUST OCCUR prior to the event this action is executed at.';
            app.inheritTankMassFromSpecEvtRadioBtn.Text = 'Specified Event:';
            app.inheritTankMassSpecEvtCombo = uidropdown(app.inheritTankMassButtonGroup);
            app.inheritTankMassSpecEvtCombo.Items = {'Pop-up Menu'};
            app.inheritTankMassSpecEvtCombo.ValueChangedFcn = createCallbackFcn(app, @inheritTankMassSpecEvtComboValueChanged, true);
            app.inheritTankMassSpecEvtCombo.Tag = 'inheritTankMassSpecEvtCombo';
            app.inheritTankMassSpecEvtCombo.Tooltip = 'The spacecraft stage states (masses, etc) will be inherited from the final state of this event.  MUST BE an event which is executed prior to this event.';
            app.inheritTankMassSpecEvtCombo.Value = 'Pop-up Menu';
            app.TankMassPanel = uipanel(app.GridLayout20);
            app.TankMassPanel.Title = 'Tank Mass';
            app.GridLayout21_3 = uigridlayout(app.TankMassPanel);
            app.text6_4 = uilabel(app.GridLayout21_3);
            app.text6_4.Tag = 'text6';
            app.text6_4.Text = 'Tank Mass';
            app.TankMassText = uieditfield(app.GridLayout21_3, 'text');
            app.TankMassText.ValueChangedFcn = createCallbackFcn(app, @TankMassTextValueChanged, true);
            app.TankMassText.Tag = 'TankMassText';
            app.TankMassText.Value = '0.0';
            app.TankMassUnitLabel = uilabel(app.GridLayout21_3);
            app.TankMassUnitLabel.Tag = 'TankMassUnitLabel';
            app.TankMassUnitLabel.Text = 'sec';
            app.optTankMassCheckbox = uicheckbox(app.GridLayout21_3);
            app.optTankMassCheckbox.ValueChangedFcn = createCallbackFcn(app, @optTankMassCheckboxValueChanged, true);
            app.optTankMassCheckbox.Tag = 'optTankMassCheckbox';
            app.optTankMassCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.optTankMassCheckbox.Text = 'Opt?';
            app.optTankMassCheckbox.Value = true;
            app.tankDryMassLbText = uieditfield(app.GridLayout21_3, 'text');
            app.tankDryMassLbText.ValueChangedFcn = createCallbackFcn(app, @tankDryMassLbTextValueChanged, true);
            app.tankDryMassLbText.Tag = 'tankDryMassLbText';
            app.tankDryMassLbText.Tooltip = 'Parameter lower bound.';
            app.tankDryMassLbText.Value = '0.0';
            app.tankDryMassUbText = uieditfield(app.GridLayout21_3, 'text');
            app.tankDryMassUbText.ValueChangedFcn = createCallbackFcn(app, @tankDryMassUbTextValueChanged, true);
            app.tankDryMassUbText.Tag = 'tankDryMassUbText';
            app.tankDryMassUbText.Tooltip = 'Parameter upper bound.';
            app.tankDryMassUbText.Value = '0.0';
            app.ElectricalPowerSourcesTab = uitab(app.TabGroup2);
            app.ElectricalPowerSourcesTab.Title = 'Electrical Power Sources';
            app.GridLayout22 = uigridlayout(app.ElectricalPowerSourcesTab);
            app.EpsSrcListbox = uilistbox(app.GridLayout22);
            app.EpsSrcListbox.ValueChangedFcn = createCallbackFcn(app, @EpsSrcListboxValueChanged, true);
            app.GridLayout23 = uigridlayout(app.GridLayout22);
            app.inheritEpsSrcStateCheckbox = uicheckbox(app.GridLayout23);
            app.inheritEpsSrcStateCheckbox.ValueChangedFcn = createCallbackFcn(app, @inheritEpsSrcStateCheckboxValueChanged, true);
            app.inheritEpsSrcStateCheckbox.Tag = 'inheritTankMassCheckbox';
            app.inheritEpsSrcStateCheckbox.Text = 'Inherit Electrical Power Source State';
            app.inheritEpsSrcStateCheckbox.Value = true;
            app.inheritEpsSrcStateButtonGroup = uibuttongroup(app.GridLayout23);
            app.inheritEpsSrcStateButtonGroup.Title = 'Inherit From';
            app.inheritEpsSrcStateButtonGroup.Tag = 'inheritTankMassButtonGroup';
            app.inheritEpsSrcStateFromPrevStateRadioBtn = uiradiobutton(app.inheritEpsSrcStateButtonGroup);
            app.inheritEpsSrcStateFromPrevStateRadioBtn.Tag = 'inheritTankMassFromPrevStateRadioBtn';
            app.inheritEpsSrcStateFromPrevStateRadioBtn.Tooltip = 'Selecting this will inherit the stage states (masses, etc) from the spacecraft state immediately prior to executing this action.';
            app.inheritEpsSrcStateFromPrevStateRadioBtn.Text = 'Previous State';
            app.inheritEpsSrcStateFromPrevStateRadioBtn.Value = true;
            app.inheritEpsSrcStateFromSpecEvtRadioBtn = uiradiobutton(app.inheritEpsSrcStateButtonGroup);
            app.inheritEpsSrcStateFromSpecEvtRadioBtn.Tag = 'inheritTankMassFromSpecEvtRadioBtn';
            app.inheritEpsSrcStateFromSpecEvtRadioBtn.Tooltip = 'Selecting this will inherit the stage states (masses, etc) from the spacecraft state at the end of the specified event.  Note that the specified event MUST OCCUR prior to the event this action is executed at.';
            app.inheritEpsSrcStateFromSpecEvtRadioBtn.Text = 'Specified Event:';
            app.inheritEpsSrcStateSpecEvtCombo = uidropdown(app.inheritEpsSrcStateButtonGroup);
            app.inheritEpsSrcStateSpecEvtCombo.Items = {'Pop-up Menu'};
            app.inheritEpsSrcStateSpecEvtCombo.Tag = 'inheritTankMassSpecEvtCombo';
            app.inheritEpsSrcStateSpecEvtCombo.Tooltip = 'The spacecraft stage states (masses, etc) will be inherited from the final state of this event.  MUST BE an event which is executed prior to this event.';
            app.inheritEpsSrcStateSpecEvtCombo.Value = 'Pop-up Menu';
            app.ElectricalPowerSourceStatePanel = uipanel(app.GridLayout23);
            app.ElectricalPowerSourceStatePanel.Title = 'Electrical Power Source State';
            app.GridLayout21_4 = uigridlayout(app.ElectricalPowerSourceStatePanel);
            app.epsSrcActiveCheckbox = uicheckbox(app.GridLayout21_4);
            app.epsSrcActiveCheckbox.Tag = 'engineActiveCheckbox';
            app.epsSrcActiveCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.epsSrcActiveCheckbox.Text = 'EPS Source is Active?';
            app.epsSrcActiveCheckbox.Value = true;
            app.ElectricalPowerStorageTab = uitab(app.TabGroup2);
            app.ElectricalPowerStorageTab.Title = 'Electrical Power Storage';
            app.GridLayout24 = uigridlayout(app.ElectricalPowerStorageTab);
            app.EpsStorageListbox = uilistbox(app.GridLayout24);
            app.EpsStorageListbox.ValueChangedFcn = createCallbackFcn(app, @EpsStorageListboxValueChanged, true);
            app.GridLayout25 = uigridlayout(app.GridLayout24);
            app.inheritEpsStorageStateCheckbox = uicheckbox(app.GridLayout25);
            app.inheritEpsStorageStateCheckbox.ValueChangedFcn = createCallbackFcn(app, @inheritEpsStorageStateCheckboxValueChanged, true);
            app.inheritEpsStorageStateCheckbox.Tag = 'inheritTankMassCheckbox';
            app.inheritEpsStorageStateCheckbox.Text = 'Inherit Electrical Power Storage State';
            app.inheritEpsStorageStateCheckbox.Value = true;
            app.inheritEpsStorageStateButtonGroup = uibuttongroup(app.GridLayout25);
            app.inheritEpsStorageStateButtonGroup.Title = 'Inherit From';
            app.inheritEpsStorageStateButtonGroup.Tag = 'inheritTankMassButtonGroup';
            app.inheritEpsStorageStateFromPrevStateRadioBtn = uiradiobutton(app.inheritEpsStorageStateButtonGroup);
            app.inheritEpsStorageStateFromPrevStateRadioBtn.Tag = 'inheritTankMassFromPrevStateRadioBtn';
            app.inheritEpsStorageStateFromPrevStateRadioBtn.Tooltip = 'Selecting this will inherit the stage states (masses, etc) from the spacecraft state immediately prior to executing this action.';
            app.inheritEpsStorageStateFromPrevStateRadioBtn.Text = 'Previous State';
            app.inheritEpsStorageStateFromPrevStateRadioBtn.Value = true;
            app.inheritEpsStorageStateFromSpecEvtRadioBtn = uiradiobutton(app.inheritEpsStorageStateButtonGroup);
            app.inheritEpsStorageStateFromSpecEvtRadioBtn.Tag = 'inheritTankMassFromSpecEvtRadioBtn';
            app.inheritEpsStorageStateFromSpecEvtRadioBtn.Tooltip = 'Selecting this will inherit the stage states (masses, etc) from the spacecraft state at the end of the specified event.  Note that the specified event MUST OCCUR prior to the event this action is executed at.';
            app.inheritEpsStorageStateFromSpecEvtRadioBtn.Text = 'Specified Event:';
            app.inheritEpsStorageStateSpecEvtCombo = uidropdown(app.inheritEpsStorageStateButtonGroup);
            app.inheritEpsStorageStateSpecEvtCombo.Items = {'Pop-up Menu'};
            app.inheritEpsStorageStateSpecEvtCombo.Tag = 'inheritTankMassSpecEvtCombo';
            app.inheritEpsStorageStateSpecEvtCombo.Tooltip = 'The spacecraft stage states (masses, etc) will be inherited from the final state of this event.  MUST BE an event which is executed prior to this event.';
            app.inheritEpsStorageStateSpecEvtCombo.Value = 'Pop-up Menu';
            app.ElectricalPowerStorageStatePanel = uipanel(app.GridLayout25);
            app.ElectricalPowerStorageStatePanel.Title = 'Electrical Power Source State';
            app.GridLayout21_5 = uigridlayout(app.ElectricalPowerStorageStatePanel);
            app.GridLayout26 = uigridlayout(app.GridLayout21_5);
            app.epsStorageActiveCheckbox = uicheckbox(app.GridLayout26);
            app.epsStorageActiveCheckbox.Tag = 'engineActiveCheckbox';
            app.epsStorageActiveCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.epsStorageActiveCheckbox.Text = 'EPS Storage is Active?';
            app.epsStorageActiveCheckbox.Value = true;
            app.GridLayout27 = uigridlayout(app.GridLayout21_5);
            app.text6_8 = uilabel(app.GridLayout27);
            app.text6_8.Tag = 'text6';
            app.text6_8.Text = 'Storage SoC';
            app.StorageSoCText = uieditfield(app.GridLayout27, 'text');
            app.StorageSoCText.ValueChangedFcn = createCallbackFcn(app, @StorageSoCTextValueChanged, true);
            app.StorageSoCText.Tag = 'TankMassText';
            app.StorageSoCText.Value = '0.0';
            app.StorageSoCUnitLabel = uilabel(app.GridLayout27);
            app.StorageSoCUnitLabel.Tag = 'TankMassUnitLabel';
            app.StorageSoCUnitLabel.Text = 'EC';
            app.optStorageSoCCheckbox = uicheckbox(app.GridLayout27);
            app.optStorageSoCCheckbox.ValueChangedFcn = createCallbackFcn(app, @optStorageSoCCheckboxValueChanged, true);
            app.optStorageSoCCheckbox.Tag = 'optTankMassCheckbox';
            app.optStorageSoCCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.optStorageSoCCheckbox.Text = 'Opt?';
            app.optStorageSoCCheckbox.Value = true;
            app.storageSoCLbText = uieditfield(app.GridLayout27, 'text');
            app.storageSoCLbText.ValueChangedFcn = createCallbackFcn(app, @storageSoCLbTextValueChanged, true);
            app.storageSoCLbText.Tag = 'tankDryMassLbText';
            app.storageSoCLbText.Tooltip = 'Parameter lower bound.';
            app.storageSoCLbText.Value = '0.0';
            app.storageSoCUbText = uieditfield(app.GridLayout27, 'text');
            app.storageSoCUbText.ValueChangedFcn = createCallbackFcn(app, @storageSoCUbTextValueChanged, true);
            app.storageSoCUbText.Tag = 'tankDryMassUbText';
            app.storageSoCUbText.Tooltip = 'Parameter upper bound.';
            app.storageSoCUbText.Value = '0.0';
            app.ElectricalPowerSinksTab = uitab(app.TabGroup2);
            app.ElectricalPowerSinksTab.Title = 'Electrical Power Sinks';
            app.GridLayout28 = uigridlayout(app.ElectricalPowerSinksTab);
            app.EpsSinkListbox = uilistbox(app.GridLayout28);
            app.EpsSinkListbox.ValueChangedFcn = createCallbackFcn(app, @EpsSinkListboxValueChanged, true);
            app.GridLayout29 = uigridlayout(app.GridLayout28);
            app.inheritEpsSinkStateCheckbox = uicheckbox(app.GridLayout29);
            app.inheritEpsSinkStateCheckbox.ValueChangedFcn = createCallbackFcn(app, @inheritEpsSinkStateCheckboxValueChanged, true);
            app.inheritEpsSinkStateCheckbox.Tag = 'inheritTankMassCheckbox';
            app.inheritEpsSinkStateCheckbox.Text = 'Inherit Electrical Power Sink State';
            app.inheritEpsSinkStateCheckbox.Value = true;
            app.inheritEpsSinkStateButtonGroup = uibuttongroup(app.GridLayout29);
            app.inheritEpsSinkStateButtonGroup.Title = 'Inherit From';
            app.inheritEpsSinkStateButtonGroup.Tag = 'inheritTankMassButtonGroup';
            app.inheritEpsSinkStateFromPrevStateRadioBtn = uiradiobutton(app.inheritEpsSinkStateButtonGroup);
            app.inheritEpsSinkStateFromPrevStateRadioBtn.Tag = 'inheritTankMassFromPrevStateRadioBtn';
            app.inheritEpsSinkStateFromPrevStateRadioBtn.Tooltip = 'Selecting this will inherit the stage states (masses, etc) from the spacecraft state immediately prior to executing this action.';
            app.inheritEpsSinkStateFromPrevStateRadioBtn.Text = 'Previous State';
            app.inheritEpsSinkStateFromPrevStateRadioBtn.Value = true;
            app.inheritEpsSinkStateFromSpecEvtRadioBtn = uiradiobutton(app.inheritEpsSinkStateButtonGroup);
            app.inheritEpsSinkStateFromSpecEvtRadioBtn.Tag = 'inheritTankMassFromSpecEvtRadioBtn';
            app.inheritEpsSinkStateFromSpecEvtRadioBtn.Tooltip = 'Selecting this will inherit the stage states (masses, etc) from the spacecraft state at the end of the specified event.  Note that the specified event MUST OCCUR prior to the event this action is executed at.';
            app.inheritEpsSinkStateFromSpecEvtRadioBtn.Text = 'Specified Event:';
            app.inheritEpsSinkStateSpecEvtCombo = uidropdown(app.inheritEpsSinkStateButtonGroup);
            app.inheritEpsSinkStateSpecEvtCombo.Items = {'Pop-up Menu'};
            app.inheritEpsSinkStateSpecEvtCombo.Tag = 'inheritTankMassSpecEvtCombo';
            app.inheritEpsSinkStateSpecEvtCombo.Tooltip = 'The spacecraft stage states (masses, etc) will be inherited from the final state of this event.  MUST BE an event which is executed prior to this event.';
            app.inheritEpsSinkStateSpecEvtCombo.Value = 'Pop-up Menu';
            app.ElectricalPowerSinkStatePanel = uipanel(app.GridLayout29);
            app.ElectricalPowerSinkStatePanel.Title = 'Electrical Power Source State';
            app.GridLayout21_6 = uigridlayout(app.ElectricalPowerSinkStatePanel);
            app.epsSinkActiveCheckbox = uicheckbox(app.GridLayout21_6);
            app.epsSinkActiveCheckbox.Tag = 'engineActiveCheckbox';
            app.epsSinkActiveCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.epsSinkActiveCheckbox.Text = 'EPS Source is Active?';
            app.epsSinkActiveCheckbox.Value = true;
            app.OtherStateElementsTab = uitab(app.TabGroup);
            app.OtherStateElementsTab.Title = 'Other State Elements';
            app.GridLayout12 = uigridlayout(app.OtherStateElementsTab);
            app.GridLayout13 = uigridlayout(app.GridLayout12);
            app.inheritOtherStateElementsCheckbox = uicheckbox(app.GridLayout13);
            app.inheritOtherStateElementsCheckbox.Tag = 'inheritOtherStateElementsCheckbox';
            app.inheritOtherStateElementsCheckbox.Enable = 'off';
            app.inheritOtherStateElementsCheckbox.Text = 'Inherit Other State Elements';
            app.inheritOtherStateElementsCheckbox.Value = true;
            app.text24 = uilabel(app.GridLayout13);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Inherits the steering model, throttle model, aerodynamics state, third body gravity, stopwatch states, and extrema states.';
            app.inheritOtherStateElementsButtonGroup = uibuttongroup(app.GridLayout12);
            app.inheritOtherStateElementsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @inheritOtherStateElementsButtonGroup_SelectionChangedFcn, true);
            app.inheritOtherStateElementsButtonGroup.Title = 'Inherit From';
            app.inheritOtherStateElementsButtonGroup.Tag = 'inheritOtherStateElementsButtonGroup';
            app.inheritOtherStateElementsFromPrevStateRadioBtn = uiradiobutton(app.inheritOtherStateElementsButtonGroup);
            app.inheritOtherStateElementsFromPrevStateRadioBtn.Tag = 'inheritOtherStateElementsFromPrevStateRadioBtn';
            app.inheritOtherStateElementsFromPrevStateRadioBtn.Tooltip = 'Selecting this will inherit the other state elements from the spacecraft state immediately prior to executing this action.';
            app.inheritOtherStateElementsFromPrevStateRadioBtn.Text = 'Previous State';
            app.inheritOtherStateElementsFromPrevStateRadioBtn.Value = true;
            app.inheritOtherStateElementsFromSpecEvtRadioBtn = uiradiobutton(app.inheritOtherStateElementsButtonGroup);
            app.inheritOtherStateElementsFromSpecEvtRadioBtn.Tag = 'inheritOtherStateElementsFromSpecEvtRadioBtn';
            app.inheritOtherStateElementsFromSpecEvtRadioBtn.Tooltip = 'Selecting this will inherit the other state elements from the spacecraft state at the end of the specified event.  Note that the specified event MUST OCCUR prior to the event this action is executed at.';
            app.inheritOtherStateElementsFromSpecEvtRadioBtn.Text = 'Specified Event:';
            app.inheritOtherStateElementsEvtCombo = uidropdown(app.inheritOtherStateElementsButtonGroup);
            app.inheritOtherStateElementsEvtCombo.Items = {'Pop-up Menu'};
            app.inheritOtherStateElementsEvtCombo.Tag = 'inheritOtherStateElementsEvtCombo';
            app.inheritOtherStateElementsEvtCombo.Tooltip = 'The other elements of the spacecraft state will be inherited from the final state of this event.  MUST BE an event which is executed prior to this event.';
            app.inheritOtherStateElementsEvtCombo.Value = 'Pop-up Menu';
            app.text25 = uilabel(app.GridLayout12);
            app.text25.Tag = 'text25';
            app.text25.Text = 'If you inherit time and the other state elements from different events, you may need to reinitialize the throttle model and steering model by adding Set Throttle Model and Set Steering Model actions after this action.';
            app.text23 = uilabel(app.GridLayout);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Avoid using this action with other event actions that modify the time, position, velocity, or mass of the spacecraft.  The best practice is to create a zero duration event and use only this action on that event.';
            app.epochContextMenu = uicontextmenu(app.lvd_EditActionSetKinematicStateGUI);
            app.epochContextMenu.Tag = 'epochContextMenu';
            app.enterUTAsDateTimeContextMenu = uimenu(app.epochContextMenu);
            app.enterUTAsDateTimeContextMenu.MenuSelectedFcn = createCallbackFcn(app, @enterUTAsDateTimeContextMenu_Callback, true);
            app.enterUTAsDateTimeContextMenu.Text = 'Enter UT As Date/Time';
            app.enterUTAsDateTimeContextMenu.Tag = 'enterUTAsDateTimeContextMenu';
            % Assign app.epochContextMenu
            app.utText.ContextMenu = app.epochContextMenu;
            app.TankMassText.ContextMenu = app.epochContextMenu;
            app.StorageSoCText.ContextMenu = app.epochContextMenu;
            app.orbitPanelContextMenu = uicontextmenu(app.lvd_EditActionSetKinematicStateGUI);
            app.orbitPanelContextMenu.Tag = 'orbitPanelContextMenu';
            app.getOrbitFromSFSFileContextMenu = uimenu(app.orbitPanelContextMenu);
            app.getOrbitFromSFSFileContextMenu.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromSFSFileContextMenu_Callback, true);
            app.getOrbitFromSFSFileContextMenu.Text = 'Get Orbit From SFS File';
            app.getOrbitFromSFSFileContextMenu.Tag = 'getOrbitFromSFSFileContextMenu';
            app.getOrbitFromKSPTOTConnectContextMenu = uimenu(app.orbitPanelContextMenu);
            app.getOrbitFromKSPTOTConnectContextMenu.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromKSPTOTConnectContextMenu_Callback, true);
            app.getOrbitFromKSPTOTConnectContextMenu.Text = 'Get Orbit From KSP';
            app.getOrbitFromKSPTOTConnectContextMenu.Tag = 'getOrbitFromKSPTOTConnectContextMenu';
            app.getOrbitFromKSPActiveVesselMenu = uimenu(app.orbitPanelContextMenu);
            app.getOrbitFromKSPActiveVesselMenu.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromKSPActiveVesselMenu_Callback, true);
            app.getOrbitFromKSPActiveVesselMenu.Text = 'Get Orbit From KSP (Active Vessel)';
            app.getOrbitFromKSPActiveVesselMenu.Tag = 'getOrbitFromKSPActiveVesselMenu';
            app.copyOrbitToClipboardMenu = uimenu(app.orbitPanelContextMenu);
            app.copyOrbitToClipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @copyOrbitToClipboardMenu_Callback, true);
            app.copyOrbitToClipboardMenu.Text = 'Copy Orbit to Clipboard';
            app.copyOrbitToClipboardMenu.Tag = 'copyOrbitToClipboardMenu';
            app.pasteOrbitFromClipboardMenu = uimenu(app.orbitPanelContextMenu);
            app.pasteOrbitFromClipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @pasteOrbitFromClipboardMenu_Callback, true);
            app.pasteOrbitFromClipboardMenu.Text = 'Paste Orbit from Clipboard';
            app.pasteOrbitFromClipboardMenu.Tag = 'pasteOrbitFromClipboardMenu';
            % Assign app.orbitPanelContextMenu
            app.orbit1Label.ContextMenu = app.orbitPanelContextMenu;
            app.orbit2Label.ContextMenu = app.orbitPanelContextMenu;
            app.orbit3Label.ContextMenu = app.orbitPanelContextMenu;
            app.orbit4Label.ContextMenu = app.orbitPanelContextMenu;
            app.orbit5Label.ContextMenu = app.orbitPanelContextMenu;
            app.orbit6Label.ContextMenu = app.orbitPanelContextMenu;
            app.orbit1Text.ContextMenu = app.orbitPanelContextMenu;
            app.orbit2Text.ContextMenu = app.orbitPanelContextMenu;
            app.orbit3Text.ContextMenu = app.orbitPanelContextMenu;
            app.orbit4Text.ContextMenu = app.orbitPanelContextMenu;
            app.orbit5Text.ContextMenu = app.orbitPanelContextMenu;
            app.orbit6Text.ContextMenu = app.orbitPanelContextMenu;
            app.orbit1UnitLabel.ContextMenu = app.orbitPanelContextMenu;
            app.orbit2UnitLabel.ContextMenu = app.orbitPanelContextMenu;
            app.orbit3UnitLabel.ContextMenu = app.orbitPanelContextMenu;
            app.orbit4UnitLabel.ContextMenu = app.orbitPanelContextMenu;
            app.orbit5UnitLabel.ContextMenu = app.orbitPanelContextMenu;
            app.orbit6UnitLabel.ContextMenu = app.orbitPanelContextMenu;
            app.optOrbit1Checkbox.ContextMenu = app.orbitPanelContextMenu;
            app.optOrbit2Checkbox.ContextMenu = app.orbitPanelContextMenu;
            app.optOrbit3Checkbox.ContextMenu = app.orbitPanelContextMenu;
            app.optOrbit4Checkbox.ContextMenu = app.orbitPanelContextMenu;
            app.optOrbit5Checkbox.ContextMenu = app.orbitPanelContextMenu;
            app.optOrbit6Checkbox.ContextMenu = app.orbitPanelContextMenu;
            app.orbit1LbText.ContextMenu = app.orbitPanelContextMenu;
            app.orbit2LbText.ContextMenu = app.orbitPanelContextMenu;
            app.orbit3LbText.ContextMenu = app.orbitPanelContextMenu;
            app.orbit4LbText.ContextMenu = app.orbitPanelContextMenu;
            app.orbit5LbText.ContextMenu = app.orbitPanelContextMenu;
            app.orbit6LbText.ContextMenu = app.orbitPanelContextMenu;
            app.orbit1UbText.ContextMenu = app.orbitPanelContextMenu;
            app.orbit2UbText.ContextMenu = app.orbitPanelContextMenu;
            app.orbit3UbText.ContextMenu = app.orbitPanelContextMenu;
            app.orbit4UbText.ContextMenu = app.orbitPanelContextMenu;
            app.orbit5UbText.ContextMenu = app.orbitPanelContextMenu;
            app.orbit6UbText.ContextMenu = app.orbitPanelContextMenu;
            % Show the figure after all components are created
            app.lvd_EditActionSetKinematicStateGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditActionSetKinematicStateGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditActionSetKinematicStateGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditActionSetKinematicStateGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditActionSetKinematicStateGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditActionSetKinematicStateGUI)
        end
    end
end