classdef lvd_EditActionSetPluginVarGaTaskValueGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditActionSetPluginVarValueGUI  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        uipanel1                  matlab.ui.container.Panel
        GridLayout4               matlab.ui.container.GridLayout
        referenceFrameSelectComp  referenceFrameSelectComp
        GridLayout3               matlab.ui.container.GridLayout
        TaskListDropdown          matlab.ui.control.DropDown
        PluginVarsCombo           matlab.ui.control.DropDown
        text5                     matlab.ui.control.Label
        text3                     matlab.ui.control.Label
        GridLayout2               matlab.ui.container.GridLayout
        cancelButton              matlab.ui.control.Button
        saveAndCloseButton        matlab.ui.control.Button
        text2                     matlab.ui.control.Label
    end


    properties (Access = private)
        action SetLvdPluginVarGaTaskValueAction
        lvdData LvdData
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app)
            [listBoxStr, pluginVars] = app.lvdData.pluginVars.getListboxStr();
            
            app.PluginVarsCombo.Items = listBoxStr;
            app.PluginVarsCombo.ItemsData = pluginVars;
            app.PluginVarsCombo.Value = app.action.pluginVar;

            taskList = lvd_getGraphAnalysisTaskList(app.lvdData, getLvdGAExcludeList());
            app.TaskListDropdown.Items = taskList;
            app.TaskListDropdown.Value = app.action.taskStr;

            app.referenceFrameSelectComp.initializeWithFrames(app.lvdData);
            app.referenceFrameSelectComp.setSelectedFrame(app.action.frame);
        end

        function errMsg = validateInputs(app)
            errMsg = {};
        end

        function saveAndClose(app)
            app.action.pluginVar = app.PluginVarsCombo.Value;

            app.action.taskStr = app.TaskListDropdown.Value;
            app.action.frame = app.referenceFrameSelectComp.getSelectedFrame();
            app.action.task = GraphicalAnalysisTask(app.action.taskStr, app.action.frame);

            app.output.output = {true};
            delete(app);
        end

        function validateSaveAndClose(app)
            errMsg = app.validateInputs();

            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.lvd_EditActionSetPluginVarValueGUI, errMsg, 'Invalid Inputs', "Icon","error");
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditActionSetPluginVarValueGUI_OpeningFcn(app, action, lvdData, output)
            centerUIFigure(app.lvd_EditActionSetPluginVarValueGUI);
            applySelectedThemeToApp(app);

            app.action = action;
            app.lvdData = lvdData;
            app.output = output;

            app.populateGUI();

            % UIWAIT makes lvd_EditActionSetTankToTankConnFlowRateGUI wait for user response (see UIRESUME)
            uiwait(app.lvd_EditActionSetPluginVarValueGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            close(app.lvd_EditActionSetPluginVarValueGUI);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            app.validateSaveAndClose();
        end

        % Window key press function: lvd_EditActionSetPluginVarValueGUI
        function lvd_EditActionSetPluginVarValueGUIWindowKeyPress(app, event)
            key = event.Key;
            switch(key)
                case 'return'
                    app.validateSaveAndClose();
                case 'enter'
                    app.validateSaveAndClose();
                case 'escape'
                    close(app.lvd_EditActionSetPluginVarValueGUI);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditActionSetPluginVarValueGUI = uifigure('Visible', 'off');
            app.lvd_EditActionSetPluginVarValueGUI.Name = 'Edit Action';
            app.lvd_EditActionSetPluginVarValueGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditActionSetPluginVarValueGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditActionSetPluginVarValueGUIWindowKeyPress, true);
            app.lvd_EditActionSetPluginVarValueGUI.Tag = 'lvd_EditActionSetPluginVarValueGUI';
            app.GridLayout = uigridlayout(app.lvd_EditActionSetPluginVarValueGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Action';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout4 = uigridlayout(app.uipanel1);
            app.GridLayout3 = uigridlayout(app.GridLayout4);
            app.text3 = uilabel(app.GridLayout3);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Plugin Variable';
            app.text5 = uilabel(app.GridLayout3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Quantity';
            app.PluginVarsCombo = uidropdown(app.GridLayout3);
            app.PluginVarsCombo.Items = {'Pop-up Menu'};
            app.PluginVarsCombo.Tag = 'refConnCombo';
            app.PluginVarsCombo.Value = 'Pop-up Menu';
            app.TaskListDropdown = uidropdown(app.GridLayout3);
            app.referenceFrameSelectComp = referenceFrameSelectComp(app.GridLayout4);
            % Show the figure after all components are created
            app.lvd_EditActionSetPluginVarValueGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditActionSetPluginVarGaTaskValueGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditActionSetPluginVarValueGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditActionSetPluginVarValueGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditActionSetPluginVarValueGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditActionSetPluginVarValueGUI)
        end
    end
end