classdef lvd_EditActionSetPwrSrcStateGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditActionSetPwrSrcStateGUI  matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        uipanel1            matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        stateCombo          matlab.ui.control.DropDown
        refPwrCompCombo     matlab.ui.control.DropDown
        text4               matlab.ui.control.Label
        text3               matlab.ui.control.Label
        GridLayout2         matlab.ui.container.GridLayout
        cancelButton        matlab.ui.control.Button
        saveAndCloseButton  matlab.ui.control.Button
        text2               matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, action, lv)
            [powerSrcListStr, powerSrcs] = lv.getPowerSrcsListBoxStr();
            set(handles.refPwrCompCombo,'String',powerSrcListStr);

            if(not(isempty(action.src)))
                ind = find(powerSrcs == action.src,1,'first');
            else
                ind = [];
            end

            if(not(isempty(ind)))
                set(handles.refPwrCompCombo,'Value',ind);
            end

            if(action.activeStateToSet)
                set(handles.stateCombo,'Value',1);
            else
                set(handles.stateCombo,'Value',2);
            end
        end


        function saveAndClose(app, handles)
            action = getappdata(handles.lvd_EditActionSetPwrSrcStateGUI,'action');
            lv = getappdata(handles.lvd_EditActionSetPwrSrcStateGUI,'lv');

            [~, powerSrcs] = lv.getPowerSrcsListBoxStr();
            ind = get(handles.refPwrCompCombo,'Value');
            action.src = powerSrcs(ind);

            contents = cellstr(get(handles.stateCombo,'String'));
            stateStr = contents{get(handles.stateCombo,'Value')};

            switch stateStr
                case 'Active'
                    state = true;
                case 'Inactive'
                    state = false;
                otherwise
                    error('Invalid state string: %s', stateStr);
            end

            action.activeStateToSet = state;

            app.output.output = {true};
            close(handles.lvd_EditActionSetPwrSrcStateGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditActionSetPwrSrcStateGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditActionSetPwrSrcStateGUI (see VARARGIN)

            % Choose default command line output for lvd_EditActionSetPwrSrcStateGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            action = varargin{1};
            setappdata(hObject,'action',action);

            lv = varargin{2};
            setappdata(hObject,'lv',lv);

            app.output = varargin{3};

            populateGUI(app, handles, action, lv);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditActionSetPwrSrcStateGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditActionSetPwrSrcStateGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditActionSetPwrSrcStateGUI);
        end

        % Window key press function: lvd_EditActionSetPwrSrcStateGUI
        function lvd_EditActionSetPwrSrcStateGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditActionSetPwrSrcStateGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditActionSetPwrSrcStateGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %                 uiresume(handles.lvd_EditActionSetPwrSrcStateGUI);
            app.saveAndClose(handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditActionSetPwrSrcStateGUI = uifigure('Visible', 'off');
            app.lvd_EditActionSetPwrSrcStateGUI.Name = 'Edit Action';
            app.lvd_EditActionSetPwrSrcStateGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditActionSetPwrSrcStateGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditActionSetPwrSrcStateGUI_WindowKeyPressFcn, true);
            app.lvd_EditActionSetPwrSrcStateGUI.Tag = 'lvd_EditActionSetPwrSrcStateGUI';
            app.GridLayout = uigridlayout(app.lvd_EditActionSetPwrSrcStateGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Action';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.text3 = uilabel(app.GridLayout3);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Ref. Power Source';
            app.text4 = uilabel(app.GridLayout3);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Pwr Source State';
            app.refPwrCompCombo = uidropdown(app.GridLayout3);
            app.refPwrCompCombo.Items = {'Pop-up Menu'};
            app.refPwrCompCombo.Tag = 'refPwrCompCombo';
            app.refPwrCompCombo.Value = 'Pop-up Menu';
            app.stateCombo = uidropdown(app.GridLayout3);
            app.stateCombo.Items = {'Active', 'Inactive'};
            app.stateCombo.Tag = 'stateCombo';
            app.stateCombo.Value = 'Active';
            % Show the figure after all components are created
            app.lvd_EditActionSetPwrSrcStateGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditActionSetPwrSrcStateGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditActionSetPwrSrcStateGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditActionSetPwrSrcStateGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditActionSetPwrSrcStateGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditActionSetPwrSrcStateGUI)
        end
    end
end