classdef lvd_EditActionSetSensorMaxRangeGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditActionUIFigure  matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        Panel               matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        GridLayout4         matlab.ui.container.GridLayout
        text6               matlab.ui.control.Label
        SensorMaxRange      matlab.ui.control.EditField
        SensorCombo         matlab.ui.control.DropDown
        MaxRangeLabel       matlab.ui.control.Label
        SensorLabel         matlab.ui.control.Label
        GridLayout2         matlab.ui.container.GridLayout
        cancelButton        matlab.ui.control.Button
        saveAndCloseButton  matlab.ui.control.Button
        text2               matlab.ui.control.Label
    end

    
    properties (Access = private)
        action SetSensorMaxRangeAction
        lvdData LvdData
        output AppDesignerGUIOutput
    end
    
    methods (Access = private)
        function populateGUI(app)
            [listboxStr, sensors] = app.lvdData.sensors.getListboxStr();
            app.SensorCombo.Items = listboxStr;
            app.SensorCombo.ItemsData = sensors;
            
            if(not(isempty(app.action.sensor)))
                app.SensorCombo.Value = app.action.sensor;
            else
                app.SensorCombo.Value = sensors(1);
            end
            
            app.SensorMaxRange.Value = fullAccNum2Str(app.action.sensorMaxRange);
        end
        
        function saveAndClose(app)
            errMsg = validateInputs(app);
            
            if(isempty(errMsg))
                app.action.sensor = app.SensorCombo.Value;
                app.action.sensorMaxRange = str2double(app.SensorMaxRange.Value);
                
                app.output.output = {true};
                close(app.EditActionUIFigure);
            else
                uialert(app.EditActionUIFigure, errMsg, 'Invalid Action Inputs', "Icon","error");
            end
        end
        
        function errMsg = validateInputs(app)
            errMsg = {};

            value = str2double(app.SensorMaxRange.Value);
            enteredStr = app.SensorMaxRange.Value;
            numberName = 'Sensor Max Range';
            lb = 0.001;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            centerUIFigure(app.EditActionUIFigure);
            applySelectedThemeToApp(app);

            app.action = varargin{1};
            app.lvdData = varargin{2};
            app.output = varargin{3};

            populateGUI(app);
            
            uiwait(app.EditActionUIFigure);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButtonPushed(app, event)
            app.saveAndClose();
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            close(app.EditActionUIFigure);
        end

        % Window key press function: EditActionUIFigure
        function EditActionUIFigureWindowKeyPress(app, event)
            switch(event.Key)
                case 'return'
                    app.saveAndClose();
                case 'enter'
                    app.saveAndClose();
                case 'escape'
                    close(app.EditActionUIFigure);
            end
        end

        % Value changed function: SensorMaxRange
        function SensorMaxRangeValueChanged(app, event)
            newInput = app.SensorMaxRange.Value;
            newInput = attemptStrEval(newInput);
            app.SensorMaxRange.Value = newInput;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditActionUIFigure = uifigure('Visible', 'off');
            app.EditActionUIFigure.Name = 'Edit Action';
            app.EditActionUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.EditActionUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @EditActionUIFigureWindowKeyPress, true);
            app.GridLayout = uigridlayout(app.EditActionUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Action';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButtonPushed, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.SensorLabel = uilabel(app.GridLayout3);
            app.SensorLabel.Text = 'Sensor';
            app.MaxRangeLabel = uilabel(app.GridLayout3);
            app.MaxRangeLabel.Text = 'Max. Range';
            app.SensorCombo = uidropdown(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.SensorMaxRange = uieditfield(app.GridLayout4, 'text');
            app.SensorMaxRange.ValueChangedFcn = createCallbackFcn(app, @SensorMaxRangeValueChanged, true);
            app.text6 = uilabel(app.GridLayout4);
            app.text6.Tag = 'text6';
            app.text6.Text = 'km';
            % Show the figure after all components are created
            app.EditActionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditActionSetSensorMaxRangeGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditActionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditActionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditActionUIFigure)
        end
    end
end