classdef lvd_EditActionSetSensorStateGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditActionUIFigure  matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        Panel               matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        SensorStateCombo    matlab.ui.control.DropDown
        SensorCombo         matlab.ui.control.DropDown
        SensorStateLabel    matlab.ui.control.Label
        SensorLabel         matlab.ui.control.Label
        GridLayout2         matlab.ui.container.GridLayout
        cancelButton        matlab.ui.control.Button
        saveAndCloseButton  matlab.ui.control.Button
        text2               matlab.ui.control.Label
    end

    
    properties (Access = private)
        action SetSensorActiveStateAction
        lvdData LvdData
        output AppDesignerGUIOutput
    end
    
    methods (Access = private)
        function populateGUI(app)
            [listboxStr, sensors] = app.lvdData.sensors.getListboxStr();
            app.SensorCombo.Items = listboxStr;
            app.SensorCombo.ItemsData = sensors;
            
            if(not(isempty(app.action.sensor)))
                app.SensorCombo.Value = app.action.sensor;
            else
                app.SensorCombo.Value = sensors(1);
            end
            
            if(app.action.activeStateToSet)
                app.SensorStateCombo.Value = "Active";
            else
                app.SensorStateCombo.Value = "Inactive";
            end
        end
        
        function saveAndClose(app)
            app.action.sensor = app.SensorCombo.Value;
            
            stateStr = app.SensorStateCombo.Value;
            switch stateStr
                case 'Active'
                    state = true;
                case 'Inactive'
                    state = false;
                otherwise 
                    error('Invalid state string: %s', stateStr);
            end
            
            app.action.activeStateToSet = state;
            
            app.output.output = {true};
            close(app.EditActionUIFigure);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            centerUIFigure(app.EditActionUIFigure);
            applySelectedThemeToApp(app);

            app.action = varargin{1};
            app.lvdData = varargin{2};
            app.output = varargin{3};

            populateGUI(app);
            
            uiwait(app.EditActionUIFigure);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButtonPushed(app, event)
            app.saveAndClose();
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            close(app.EditActionUIFigure);
        end

        % Window key press function: EditActionUIFigure
        function EditActionUIFigureWindowKeyPress(app, event)
            switch(event.Key)
                case 'return'
                    app.saveAndClose();
                case 'enter'
                    app.saveAndClose();
                case 'escape'
                    close(app.EditActionUIFigure);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditActionUIFigure = uifigure('Visible', 'off');
            app.EditActionUIFigure.Name = 'Edit Action';
            app.EditActionUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.EditActionUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @EditActionUIFigureWindowKeyPress, true);
            app.GridLayout = uigridlayout(app.EditActionUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Action';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButtonPushed, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.SensorLabel = uilabel(app.GridLayout3);
            app.SensorLabel.Text = 'Sensor';
            app.SensorStateLabel = uilabel(app.GridLayout3);
            app.SensorStateLabel.Text = 'Sensor State';
            app.SensorCombo = uidropdown(app.GridLayout3);
            app.SensorStateCombo = uidropdown(app.GridLayout3);
            app.SensorStateCombo.Items = {'Active', 'Inactive'};
            app.SensorStateCombo.Value = 'Active';
            % Show the figure after all components are created
            app.EditActionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditActionSetSensorStateGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditActionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditActionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditActionUIFigure)
        end
    end
end