classdef lvd_EditActionSetSensorSteeringModelGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditActionUIFigure       matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        Panel                    matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        PointingModelPanel       matlab.ui.container.Panel
        GridLayout5              matlab.ui.container.GridLayout
        EditPointingModelButton  matlab.ui.control.Button
        PointingModelCombo       matlab.ui.control.DropDown
        GridLayout4              matlab.ui.container.GridLayout
        SensorCombo              matlab.ui.control.DropDown
        SensorLabel              matlab.ui.control.Label
        GridLayout2              matlab.ui.container.GridLayout
        cancelButton             matlab.ui.control.Button
        saveAndCloseButton       matlab.ui.control.Button
        text2                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        action SetSensorSteeringModelAction
        lvdData LvdData
        output AppDesignerGUIOutput
        
        fixedInCoordSysSteeringModel FixedInCoordSysSensorSteeringModel
        fixedInVehAttSteeringModel FixedInVehicleFrameSensorSteeringModel
    end
    
    methods (Access = private)
        function populateGUI(app)
            [listboxStr, sensors] = app.lvdData.sensors.getListboxStr();           
            app.SensorCombo.Items = listboxStr;
            app.SensorCombo.ItemsData = sensors;
            
            if(not(isempty(app.action.sensor)))
                app.SensorCombo.Value = app.action.sensor;
            else
                app.SensorCombo.Value = sensors(1);
            end

            if(isempty(app.action.steeringModel))
                app.action.steeringModel = FixedInVehicleFrameSensorSteeringModel(0,0,0, app.lvdData);
            end
            
            enum = app.action.steeringModel.getEnum();
            [listBoxStr, m] = SensorSteeringModelEnum.getListBoxStr();
            app.PointingModelCombo.Items = listBoxStr;
            app.PointingModelCombo.ItemsData = m;
            app.PointingModelCombo.Value = enum;
            
            switch enum
                case SensorSteeringModelEnum.FixedInCoordSys
                    app.fixedInCoordSysSteeringModel = app.action.steeringModel;
                    app.fixedInVehAttSteeringModel = FixedInVehicleFrameSensorSteeringModel(0,0,0, app.lvdData);

                case SensorSteeringModelEnum.FixedVehicleBodyFrame
                    numCoordSys = app.lvdData.geometry.coordSyses.getNumCoordSyses();
                    if(numCoordSys >= 1)
                        coordSys = app.lvdData.geometry.coordSyses.getCoordSysAtInd(1);
                        app.fixedInCoordSysSteeringModel = FixedInCoordSysSensorSteeringModel(0,0,0,coordSys, app.lvdData);
                    end

                    app.fixedInVehAttSteeringModel = app.action.steeringModel;

                otherwise
                    error('Unknown Sensor Pointing Model Type: %s', enum.name);
            end
        end
        
        function saveAndClose(app)
            app.action.sensor = app.SensorCombo.Value;
            app.action.steeringModel = app.getSelectedSteeringModel();
            
            app.output.output = {true};
            close(app.EditActionUIFigure);
        end
        
        function steeringModel = getSelectedSteeringModel(app)
            enum = app.PointingModelCombo.Value;
            switch enum
                case SensorSteeringModelEnum.FixedInCoordSys
                    steeringModel = app.fixedInCoordSysSteeringModel;

                case SensorSteeringModelEnum.FixedVehicleBodyFrame
                    steeringModel = app.fixedInVehAttSteeringModel;

                otherwise
                    error('Unknown Sensor Pointing Model Type: %s', enum.name);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            centerUIFigure(app.EditActionUIFigure);
            applySelectedThemeToApp(app);

            app.action = varargin{1};
            app.lvdData = varargin{2};
            app.output = varargin{3};

            populateGUI(app);
            
            uiwait(app.EditActionUIFigure);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButtonPushed(app, event)
            app.saveAndClose();
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            close(app.EditActionUIFigure);
        end

        % Window key press function: EditActionUIFigure
        function EditActionUIFigureWindowKeyPress(app, event)
            switch(event.Key)
                case 'return'
                    app.saveAndClose();
                case 'enter'
                    app.saveAndClose();
                case 'escape'
                    close(app.EditActionUIFigure);
            end
        end

        % Callback function
        function SensorHalfAngleTextValueChanged(app, event)
            newInput = app.SensorHalfAngleText.Value;
            newInput = attemptStrEval(newInput);
            app.SensorHalfAngleText.Value = newInput;
        end

        % Value changed function: PointingModelCombo
        function PointingModelComboValueChanged(app, event)
            enum = app.PointingModelCombo.Value;

            if(enum == SensorSteeringModelEnum.FixedInCoordSys)
                numCoordSys = app.lvdData.geometry.coordSyses.getNumCoordSyses();

                if(numCoordSys <= 0)
                    app.PointingModelCombo.Value = event.PreviousValue;
                    uialert(app.EditConicalSensorGUI, 'In order to use the Fixed in Coordinate System pointing model, there must be at least one Geometric Coordinate System in the scenario.', 'No Coordiante Systems Found', 'Icon',"error");
                    return;
                end
            end
        end

        % Button pushed function: EditPointingModelButton
        function EditPointingModelButtonPushed(app, event)
            app.getSelectedSteeringModel().openEditDialog();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditActionUIFigure = uifigure('Visible', 'off');
            app.EditActionUIFigure.Name = 'Edit Action';
            app.EditActionUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.EditActionUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @EditActionUIFigureWindowKeyPress, true);
            app.GridLayout = uigridlayout(app.EditActionUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Action';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButtonPushed, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.SensorLabel = uilabel(app.GridLayout4);
            app.SensorLabel.Text = 'Sensor';
            app.SensorCombo = uidropdown(app.GridLayout4);
            app.PointingModelPanel = uipanel(app.GridLayout3);
            app.PointingModelPanel.Title = 'Pointing Model';
            app.GridLayout5 = uigridlayout(app.PointingModelPanel);
            app.PointingModelCombo = uidropdown(app.GridLayout5);
            app.PointingModelCombo.ValueChangedFcn = createCallbackFcn(app, @PointingModelComboValueChanged, true);
            app.PointingModelCombo.Tooltip = {'Defines the model that dictates how the sensor will be pointed in space.'};
            app.EditPointingModelButton = uibutton(app.GridLayout5, 'push');
            app.EditPointingModelButton.ButtonPushedFcn = createCallbackFcn(app, @EditPointingModelButtonPushed, true);
            app.EditPointingModelButton.Text = 'Edit Pointing Model';
            % Show the figure after all components are created
            app.EditActionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditActionSetSensorSteeringModelGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditActionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditActionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditActionUIFigure)
        end
    end
end