classdef lvd_EditActionSetStageStateGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditActionSetStageStateGUI  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        uipanel1                        matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        stateCombo                      matlab.ui.control.DropDown
        refStageCombo                   matlab.ui.control.DropDown
        text4                           matlab.ui.control.Label
        text3                           matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        cancelButton                    matlab.ui.control.Button
        saveAndCloseButton              matlab.ui.control.Button
        text2                           matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, action, lv)
            [stagesListStr, stages] = lv.getStagesListBoxStr();
            set(handles.refStageCombo,'String',stagesListStr);

            if(not(isempty(action.stage)))
                ind = find(stages == action.stage,1,'first');
            else
                ind = [];
            end

            if(not(isempty(ind)))
                set(handles.refStageCombo,'Value',ind);
            end

            if(action.activeStateToSet)
                set(handles.stateCombo,'Value',1);
            else
                set(handles.stateCombo,'Value',2);
            end
        end


        function saveAndClose(app, handles)
            action = getappdata(handles.lvd_EditActionSetStageStateGUI,'action');
            lv = getappdata(handles.lvd_EditActionSetStageStateGUI,'lv');
            
            [~, stages] = lv.getStagesListBoxStr();
            ind = get(handles.refStageCombo,'Value');
            action.stage = stages(ind);
            
            contents = cellstr(get(handles.stateCombo,'String'));
            stateStr = contents{get(handles.stateCombo,'Value')};
            
            switch stateStr
                case 'Active'
                    state = true;
                case 'Inactive'
                    state = false;
                otherwise 
                    error('Invalid state string: %s', stateStr);
            end
            
            action.activeStateToSet = state;
            
            app.output.output = {true};
            close(handles.lvd_EditActionSetStageStateGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditActionSetStageStateGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditActionSetStageStateGUI (see VARARGIN)

            % Choose default command line output for lvd_EditActionSetStageStateGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            action = varargin{1};
            setappdata(hObject,'action',action);

            lv = varargin{2};
            setappdata(hObject,'lv',lv);
            
            app.output = varargin{3};

            populateGUI(app, handles, action, lv);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditActionSetStageStateGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditActionSetStageStateGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditActionSetStageStateGUI);
        end

        % Window key press function: lvd_EditActionSetStageStateGUI
        function lvd_EditActionSetStageStateGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditActionSetStageStateGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditActionSetStageStateGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
%             uiresume(handles.lvd_EditActionSetStageStateGUI);
            app.saveAndClose(handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditActionSetStageStateGUI = uifigure('Visible', 'off');
            app.lvd_EditActionSetStageStateGUI.Name = 'Edit Action';
            app.lvd_EditActionSetStageStateGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditActionSetStageStateGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditActionSetStageStateGUI_WindowKeyPressFcn, true);
            app.lvd_EditActionSetStageStateGUI.Tag = 'lvd_EditActionSetStageStateGUI';
            app.GridLayout = uigridlayout(app.lvd_EditActionSetStageStateGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Action';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.text3 = uilabel(app.GridLayout3);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Ref. Stage';
            app.text4 = uilabel(app.GridLayout3);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Stage State';
            app.refStageCombo = uidropdown(app.GridLayout3);
            app.refStageCombo.Items = {'Pop-up Menu'};
            app.refStageCombo.Tag = 'refStageCombo';
            app.refStageCombo.Value = 'Pop-up Menu';
            app.stateCombo = uidropdown(app.GridLayout3);
            app.stateCombo.Items = {'Active', 'Inactive'};
            app.stateCombo.Tag = 'stateCombo';
            app.stateCombo.Value = 'Active';
            % Show the figure after all components are created
            app.lvd_EditActionSetStageStateGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditActionSetStageStateGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditActionSetStageStateGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditActionSetStageStateGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditActionSetStageStateGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditActionSetStageStateGUI)
        end
    end
end