classdef lvd_EditActionSetStopwatchRunningStateGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditActionSetStopwatchRunningStateGUI  matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        uipanel1            matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        stateCombo          matlab.ui.control.DropDown
        refStopwatchCombo   matlab.ui.control.DropDown
        text4               matlab.ui.control.Label
        text3               matlab.ui.control.Label
        GridLayout2         matlab.ui.container.GridLayout
        cancelButton        matlab.ui.control.Button
        saveAndCloseButton  matlab.ui.control.Button
        text2               matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, action, lv)
            [swListStr, stopwatches] = lv.getStopwatchesListBoxStr();
            if(not(isempty(swListStr)))
                set(handles.refStopwatchCombo,'String',swListStr);
            else
                set(handles.refStopwatchCombo,'String',' ');
            end

            runningStateStrs = StopwatchRunningEnum.getNameStrs();
            set(handles.stateCombo,'String',runningStateStrs);

            if(not(isempty(action.stopwatch)))
                ind = find(stopwatches == action.stopwatch,1,'first');
            else
                ind = [];
            end

            if(not(isempty(ind)))
                set(handles.refStopwatchCombo,'Value',ind);
            end

            if(not(isempty(action.stopwatch)))
                ind = StopwatchRunningEnum.getIndOfListboxStrs(action.stopwatch.startOn);
            else
                ind = 1;
            end
            set(handles.stateCombo,'Value',ind);
        end


        function saveAndClose(app, handles)
            action = getappdata(handles.lvd_EditActionSetStopwatchRunningStateGUI,'action');
            lv = getappdata(handles.lvd_EditActionSetStopwatchRunningStateGUI,'lv');

            [~, stopwatches] = lv.getStopwatchesListBoxStr();
            if(not(isempty(stopwatches)))
                ind = get(handles.refStopwatchCombo,'Value');
                action.stopwatch = stopwatches(ind);
            end

            [~,m] = StopwatchRunningEnum.getNameStrs();
            action.runningStateToSet = m(get(handles.stateCombo,'Value'));

            app.output.output = {true};
            close(handles.lvd_EditActionSetStopwatchRunningStateGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditActionSetStopwatchRunningStateGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditActionSetStopwatchRunningStateGUI (see VARARGIN)

            % Choose default command line output for lvd_EditActionSetStopwatchRunningStateGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            action = varargin{1};
            setappdata(hObject,'action',action);

            lv = varargin{2};
            setappdata(hObject,'lv',lv);

            app.output = varargin{3};
            
            populateGUI(app, handles, action, lv);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditActionSetStopwatchRunningStateGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditActionSetStopwatchRunningStateGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditActionSetStopwatchRunningStateGUI);
        end

        % Window key press function: 
        % lvd_EditActionSetStopwatchRunningStateGUI
        function lvd_EditActionSetStopwatchRunningStateGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditActionSetStopwatchRunningStateGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditActionSetStopwatchRunningStateGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
%             uiresume(handles.lvd_EditActionSetStopwatchRunningStateGUI);
            app.saveAndClose(handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditActionSetStopwatchRunningStateGUI = uifigure('Visible', 'off');
            app.lvd_EditActionSetStopwatchRunningStateGUI.Name = 'Edit Action';
            app.lvd_EditActionSetStopwatchRunningStateGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditActionSetStopwatchRunningStateGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditActionSetStopwatchRunningStateGUI_WindowKeyPressFcn, true);
            app.lvd_EditActionSetStopwatchRunningStateGUI.Tag = 'lvd_EditActionSetStopwatchRunningStateGUI';
            app.GridLayout = uigridlayout(app.lvd_EditActionSetStopwatchRunningStateGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Action';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.text3 = uilabel(app.GridLayout3);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Ref. Stopwatch';
            app.text4 = uilabel(app.GridLayout3);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Running State';
            app.refStopwatchCombo = uidropdown(app.GridLayout3);
            app.refStopwatchCombo.Items = {'Pop-up Menu'};
            app.refStopwatchCombo.Tag = 'refStopwatchCombo';
            app.refStopwatchCombo.Value = 'Pop-up Menu';
            app.stateCombo = uidropdown(app.GridLayout3);
            app.stateCombo.Items = {'Active', 'Inactive'};
            app.stateCombo.Tag = 'stateCombo';
            app.stateCombo.Value = 'Active';
            % Show the figure after all components are created
            app.lvd_EditActionSetStopwatchRunningStateGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditActionSetStopwatchRunningStateGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditActionSetStopwatchRunningStateGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditActionSetStopwatchRunningStateGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditActionSetStopwatchRunningStateGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditActionSetStopwatchRunningStateGUI)
        end
    end
end