classdef lvd_EditActionSetTankToTankConnFlowRateGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditActionSetTankToTankConnFlowRateGUI  matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        uipanel1            matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        GridLayout4         matlab.ui.container.GridLayout
        flowRateText        matlab.ui.control.EditField
        text6               matlab.ui.control.Label
        refConnCombo        matlab.ui.control.DropDown
        text5               matlab.ui.control.Label
        text3               matlab.ui.control.Label
        GridLayout2         matlab.ui.container.GridLayout
        cancelButton        matlab.ui.control.Button
        saveAndCloseButton  matlab.ui.control.Button
        text2               matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, action, lv)
            [t2TConnStr, t2TConns] = lv.getTankToTankConnectionsListBoxStr();
            set(handles.refConnCombo,'String',t2TConnStr);

            if(not(isempty(action.conn)))
                ind = find(t2TConns == action.conn,1,'first');
            else
                ind = [];
            end

            if(not(isempty(ind)))
                set(handles.refConnCombo,'Value',ind);
            end

            handles.flowRateText.String = fullAccNum2Str(action.flowRateToSet);
        end

        function stateCombo_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to stateCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            val = str2double(get(handles.flowRateText,'String'));
            enteredStr = get(handles.flowRateText,'String');
            numberName = 'Flow Rate';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
        end


        function saveAndClose(app, handles)
            action = getappdata(handles.lvd_EditActionSetTankToTankConnFlowRateGUI,'action');
            lv = getappdata(handles.lvd_EditActionSetTankToTankConnFlowRateGUI,'lv');

            [~, t2TConns] = lv.getTankToTankConnectionsListBoxStr();
            ind = get(handles.refConnCombo,'Value');
            action.conn = t2TConns(ind);

            action.flowRateToSet = str2double(handles.flowRateText.String);

            app.output.output = {true};
            close(handles.lvd_EditActionSetTankToTankConnFlowRateGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditActionSetTankToTankConnFlowRateGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditActionSetTankToTankConnFlowRateGUI (see VARARGIN)

            % Choose default command line output for lvd_EditActionSetTankToTankConnFlowRateGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            action = varargin{1};
            setappdata(hObject,'action',action);

            lv = varargin{2};
            setappdata(hObject,'lv',lv);

            app.output = varargin{3};

            populateGUI(app, handles, action, lv);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditActionSetTankToTankConnFlowRateGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditActionSetTankToTankConnFlowRateGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditActionSetTankToTankConnFlowRateGUI);
        end

        % Value changed function: flowRateText
        function flowRateText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to flowRateText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of flowRateText as text
            %        str2double(get(hObject,'String')) returns contents of flowRateText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Window key press function: 
        % lvd_EditActionSetTankToTankConnFlowRateGUI
        function lvd_EditActionSetTankToTankConnFlowRateGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditActionSetTankToTankConnFlowRateGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditActionSetTankToTankConnFlowRateGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                %                     uiresume(handles.lvd_EditActionSetTankToTankConnFlowRateGUI);
                app.saveAndClose(handles);
            else
                %                     msgbox(errMsg,'Invalid Inputs','error');
                uialert(app.lvd_EditActionSetTankToTankConnFlowRateGUI, errMsg, 'Invalid Inputs', "Icon","error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditActionSetTankToTankConnFlowRateGUI = uifigure('Visible', 'off');
            app.lvd_EditActionSetTankToTankConnFlowRateGUI.Name = 'Edit Action';
            app.lvd_EditActionSetTankToTankConnFlowRateGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditActionSetTankToTankConnFlowRateGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditActionSetTankToTankConnFlowRateGUI_WindowKeyPressFcn, true);
            app.lvd_EditActionSetTankToTankConnFlowRateGUI.Tag = 'lvd_EditActionSetTankToTankConnFlowRateGUI';
            app.GridLayout = uigridlayout(app.lvd_EditActionSetTankToTankConnFlowRateGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Action';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.text3 = uilabel(app.GridLayout3);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Ref. Connection';
            app.text5 = uilabel(app.GridLayout3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'New Flow Rate';
            app.refConnCombo = uidropdown(app.GridLayout3);
            app.refConnCombo.Items = {'Pop-up Menu'};
            app.refConnCombo.Tag = 'refConnCombo';
            app.refConnCombo.Value = 'Pop-up Menu';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.text6 = uilabel(app.GridLayout4);
            app.text6.Tag = 'text6';
            app.text6.Text = 'mT/s';
            app.flowRateText = uieditfield(app.GridLayout4, 'text');
            app.flowRateText.ValueChangedFcn = createCallbackFcn(app, @flowRateText_Callback, true);
            app.flowRateText.Tag = 'flowRateText';
            app.flowRateText.Tooltip = 'Rate of propellent flow from source tank to destination tank.  Must be positive.  Change with an event action.';
            app.flowRateText.Value = '1';
            % Show the figure after all components are created
            app.lvd_EditActionSetTankToTankConnFlowRateGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditActionSetTankToTankConnFlowRateGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditActionSetTankToTankConnFlowRateGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditActionSetTankToTankConnFlowRateGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditActionSetTankToTankConnFlowRateGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditActionSetTankToTankConnFlowRateGUI)
        end
    end
end