classdef lvd_EditActionSetThirdBodyGravitySourcesGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditActionSetThirdBodyGravitySourcesGUI  matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        uipanel1            matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        bodiesListBox       matlab.ui.control.ListBox
        text5               matlab.ui.control.Label
        GridLayout2         matlab.ui.container.GridLayout
        cancelButton        matlab.ui.control.Button
        saveAndCloseButton  matlab.ui.control.Button
        text2               matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, action, lv)
            lvdData = lv.lvdData;
            celBodyData = lvdData.celBodyData;

            [bodiesStr, sortedBodyInfo] = ma_getSortedBodyNames(celBodyData);
            handles.bodiesListBox.String = bodiesStr;

            sortedBodyInfoArr = KSPTOT_BodyInfo.empty(1,0);
            for(i=1:length(sortedBodyInfo)) %#ok<*NO4LP>
                sortedBodyInfoArr(end+1) = sortedBodyInfo{i}; %#ok<AGROW>
            end

            curSelBodies = action.bodiesToSet;
            [~,inds] = ismember([curSelBodies.id], [sortedBodyInfoArr.id]);
            inds = inds(inds > 0);

            set(handles.bodiesListBox,'Value',inds);
        end


        function saveAndClose(app, handles)
            action = getappdata(handles.lvd_EditActionSetThirdBodyGravitySourcesGUI,'action');
            lv = getappdata(handles.lvd_EditActionSetThirdBodyGravitySourcesGUI,'lv');
            lvdData = lv.lvdData;
            celBodyData = lvdData.celBodyData;

            [~, sortedBodyInfo] = ma_getSortedBodyNames(celBodyData);
            inds = handles.bodiesListBox.Value;
            if(not(isempty(inds)))
                action.bodiesToSet = [sortedBodyInfo{inds}];
            else
                action.bodiesToSet = KSPTOT_BodyInfo.empty(1,0);
            end

            app.output.output = {true};
            close(handles.lvd_EditActionSetThirdBodyGravitySourcesGUI);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditActionSetThirdBodyGravitySourcesGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditActionSetThirdBodyGravitySourcesGUI (see VARARGIN)

            % Choose default command line output for lvd_EditActionSetThirdBodyGravitySourcesGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            action = varargin{1};
            setappdata(hObject,'action',action);

            lv = varargin{2};
            setappdata(hObject,'lv',lv);

            app.output = varargin{3};

            populateGUI(app, handles, action, lv);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditActionSetThirdBodyGravitySourcesGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditActionSetThirdBodyGravitySourcesGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditActionSetThirdBodyGravitySourcesGUI);
        end

        % Window key press function: 
        % lvd_EditActionSetThirdBodyGravitySourcesGUI
        function lvd_EditActionSetThirdBodyGravitySourcesGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditActionSetThirdBodyGravitySourcesGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditActionSetThirdBodyGravitySourcesGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %                 uiresume(handles.lvd_EditActionSetThirdBodyGravitySourcesGUI);
            app.saveAndClose(handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditActionSetThirdBodyGravitySourcesGUI = uifigure('Visible', 'off');
            app.lvd_EditActionSetThirdBodyGravitySourcesGUI.Name = 'Edit Action';
            app.lvd_EditActionSetThirdBodyGravitySourcesGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditActionSetThirdBodyGravitySourcesGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditActionSetThirdBodyGravitySourcesGUI_WindowKeyPressFcn, true);
            app.lvd_EditActionSetThirdBodyGravitySourcesGUI.Tag = 'lvd_EditActionSetThirdBodyGravitySourcesGUI';
            app.GridLayout = uigridlayout(app.lvd_EditActionSetThirdBodyGravitySourcesGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Action';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.text5 = uilabel(app.GridLayout3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Select the bodies you wish to have as gravitational sources when the 3rd Body Gravity force model is active.';
            app.bodiesListBox = uilistbox(app.GridLayout3);
            app.bodiesListBox.Items = {'Listbox'};
            app.bodiesListBox.Multiselect = 'on';
            app.bodiesListBox.Tag = 'bodiesListBox';
            app.bodiesListBox.Value = {'Listbox'};
            % Show the figure after all components are created
            app.lvd_EditActionSetThirdBodyGravitySourcesGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditActionSetThirdBodyGravitySourcesGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditActionSetThirdBodyGravitySourcesGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditActionSetThirdBodyGravitySourcesGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditActionSetThirdBodyGravitySourcesGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditActionSetThirdBodyGravitySourcesGUI)
        end
    end
end