classdef lvd_EditConditionalActionGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditActionUIFigure             matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        ELSEConditionPanel             matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        ActionsPanel_2                 matlab.ui.container.Panel
        GridLayout17_2                 matlab.ui.container.GridLayout
        GridLayout18                   matlab.ui.container.GridLayout
        RemoveELSEActionButton         matlab.ui.control.Button
        AddELSEActionButton            matlab.ui.control.Button
        ElseActionsListBox             matlab.ui.control.ListBox
        ELSEIFConditionsPanel          matlab.ui.container.Panel
        GridLayout11                   matlab.ui.container.GridLayout
        ActionsPanel_3                 matlab.ui.container.Panel
        GridLayout19                   matlab.ui.container.GridLayout
        GridLayout20                   matlab.ui.container.GridLayout
        RemoveELSEIFActionButton       matlab.ui.control.Button
        AddELSEIFActionButton          matlab.ui.control.Button
        ElseIfActionsListBox           matlab.ui.control.ListBox
        ConditionsPanel_3              matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        GridLayout8                    matlab.ui.container.GridLayout
        EditELSEIFConditionalButton    matlab.ui.control.Button
        ElseIfConditionalTextArea      matlab.ui.control.TextArea
        GridLayout6                    matlab.ui.container.GridLayout
        ElseIfConditionsListbox        matlab.ui.control.ListBox
        GridLayout7                    matlab.ui.container.GridLayout
        RemoveElseIfConditionalButton  matlab.ui.control.Button
        AddElseIfConditionalButton     matlab.ui.control.Button
        IFConditionPanel               matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        ActionsPanel                   matlab.ui.container.Panel
        GridLayout15                   matlab.ui.container.GridLayout
        GridLayout16                   matlab.ui.container.GridLayout
        RemoveIFActionButton           matlab.ui.control.Button
        AddIFActionButton              matlab.ui.control.Button
        IfActionsListBox               matlab.ui.control.ListBox
        ConditionsPanel                matlab.ui.container.Panel
        GridLayout14                   matlab.ui.container.GridLayout
        GridLayout21                   matlab.ui.container.GridLayout
        IfConditionImage               matlab.ui.control.Image
        IfConditionalTextArea          matlab.ui.control.TextArea
        EditIFConditionalButton        matlab.ui.control.Button
        SaveandCloseButton             matlab.ui.control.Button
        text2                          matlab.ui.control.Label
    end

    properties (Access = private)
        action ConditionalAction
        lvdData LvdData
        output AppDesignerGUIOutput
    end
    
    methods (Access = private)
        function populateGUI(app)
            app.populateIfActionsListbox();
            app.setIfCondStatusImage();

            app.populateElseIfConditionsListbox();
            app.populateElseIfActionsListbox();
            app.populateElseIfCondTextArea();
            app.setElseIfCondRowIcons();

            app.populateElseActionsListbox();

            app.setDeleteActionsButtonsStates();
        end

        function populateIfActionsListbox(app)
            ifActions = app.action.ifActions;
            ifActionsListboxStr = string.empty();
            for(i=1:length(ifActions))
                ifAction = ifActions(i);
                ifActionsListboxStr(end+1) = string(ifAction.getName()); %#ok<*AGROW>
            end

            app.IfActionsListBox.Items = ifActionsListboxStr;
            app.IfActionsListBox.ItemsData = ifActions;

            app.IfConditionalTextArea.Value = app.action.ifCondition.getConditionalString();
            app.IfConditionalTextArea.Tooltip = app.IfConditionalTextArea.Value;
        end

        function populateElseIfConditionsListbox(app)
            elseifConditions = app.action.elseifConditions;
            elseifCondsListboxStr = string.empty();
            for(i=1:length(elseifConditions))
                elseifCondsListboxStr(i) = sprintf('%u - %s', i, elseifConditions(i).getListboxStr());
            end

            app.ElseIfConditionsListbox.Items = elseifCondsListboxStr;
            app.ElseIfConditionsListbox.ItemsData = elseifConditions;
        end
        
        function populateElseIfActionsListbox(app)
            elseIfConditionalSel = app.ElseIfConditionsListbox.Value;
            if(not(isempty(elseIfConditionalSel)))
                elseifActions = app.action.elseifActions{elseIfConditionalSel};

                if(iscell(elseifActions))
                    elseifActions = [elseifActions{:}];
                end
    
                elseIfActionsListboxStr = string.empty();
                for(i=1:length(elseifActions))
                    elseifAction = elseifActions(i);
                    elseIfActionsListboxStr(end+1) = string(elseifAction.getName()); %#ok<*AGROW>
                end
    
                app.ElseIfActionsListBox.Items = elseIfActionsListboxStr;
                app.ElseIfActionsListBox.ItemsData = elseifActions;
    
                app.ElseIfConditionalTextArea.Value = elseIfConditionalSel.getConditionalString();
                app.ElseIfConditionalTextArea.Tooltip = app.ElseIfConditionalTextArea.Value;
            else
                app.ElseIfActionsListBox.Items = string.empty(1,0);
                app.ElseIfActionsListBox.ItemsData = AbstractEventAction.empty(1,0);

                app.ElseIfConditionalTextArea.Value = "";
                app.ElseIfConditionalTextArea.Tooltip = app.ElseIfConditionalTextArea.Value;
            end
        end

        function populateElseActionsListbox(app)
            elseActions = app.action.elseActions;
            elseActionsListboxStr = string.empty();
            for(i=1:length(elseActions))
                elseAction = elseActions(i);
                elseActionsListboxStr(end+1) = string(elseAction.getName()); %#ok<*AGROW>
            end

            app.ElseActionsListBox.Items = elseActionsListboxStr;
            app.ElseActionsListBox.ItemsData = elseActions;
        end

        function [newAction, addActionTf] = createNewAction(app)
            lv = app.lvdData.launchVehicle;
            actionStrs = EventActionEnum.getActionTypeNameStrs();

            out = AppDesignerGUIOutput({[],false});
            listdlgARH_App('ListString',actionStrs, ...
                            'Name','Select Action', ...
                            'PromptString',{'Select the action you wish to add','to this event:'}, ...
                            'SelectionMode','single', ...
                            'ListSize',[210 300], ...
                            'out',out);
            dialogInd = out.output{1};
            tf = out.output{2};

            newAction = AbstractEventAction.empty(1,0);
            addActionTf = false;
            if(tf == 1)
                [m,~] = enumeration('EventActionEnum');
                inds = strcmpi({m.nameStr},actionStrs{dialogInd});

                actionEnum = m(inds);

                newAction = feval(actionEnum.classNameStr);
                newAction.event = app.action.event;
                addActionTf = newAction.openEditActionUI(newAction, lv);
            end
        end

        function setDeleteActionsButtonsStates(app)
            if(numel(app.action.ifActions) > 0)
                app.RemoveIFActionButton.Enable = true;
            else
                app.RemoveIFActionButton.Enable = false;
            end

            elseifCond = app.ElseIfConditionsListbox.Value;
            if(not(isempty(elseifCond)))
                elseifActions = app.action.elseifActions{elseifCond};
                if(iscell(elseifActions))
                    elseifActions = [elseifActions{:}];
                end

                app.AddELSEIFActionButton.Enable = true;
                if(numel(elseifActions) > 0)
                    app.RemoveELSEIFActionButton.Enable = true;
                else
                    app.RemoveELSEIFActionButton.Enable = false;
                end
            else
                app.AddELSEIFActionButton.Enable = false;
                app.RemoveELSEIFActionButton.Enable = false;
            end

            if(numel(app.action.elseActions) > 0)
                app.RemoveELSEActionButton.Enable = true;
            else
                app.RemoveELSEActionButton.Enable = false;
            end
        end

        function saveAndClose(app)
            app.output.output = {true};
            close(app.EditActionUIFigure);
        end

        function populateElseIfCondTextArea(app)
            elseIfCond = app.ElseIfConditionsListbox.Value;
            if(not(isempty(elseIfCond)))
                app.ElseIfConditionalTextArea.Value = elseIfCond.getConditionalString();
                app.ElseIfConditionalTextArea.Tooltip = app.ElseIfConditionalTextArea.Value;
            end
        end
        
        function editSelectedElseIfCondition(app)
            oldCond = app.ElseIfConditionsListbox.Value;
            
            out = AppDesignerGUIOutput({AbstractActionConditional.empty(1,0), false});
            lvd_EditActionConditionalGUI_App(oldCond, app.lvdData, out);
            newCond = out.output{1};
            tf = out.output{2};

            if(tf)
                if(oldCond ~= newCond)
                    app.action.substituteElseIfConditional(oldCond, newCond);
                end
            end

            app.populateElseIfConditionsListbox();
            app.ElseIfConditionsListbox.Value = newCond;
            app.populateElseIfActionsListbox();
            app.populateElseIfCondTextArea();
        end
        
        function editSelectedAction(app, action)   
            if(not(isempty(action)))
                lv = app.lvdData.launchVehicle;
                action.openEditActionUI(action, lv);
            end
        end

        function stateLogEntry = getStateThisActionIsAppliedTo(app)
            event = app.action.event;
            if(not(isempty(event)))
                execNode = event.execActionsNode;
                if(execNode == ActionExecNodeEnum.BeforeProp)
                    stateLogEntry = app.lvdData.stateLog.getFirstStateLogForEvent(event);

                elseif(execNode== ActionExecNodeEnum.AfterProp)
                    stateLogEntry = app.lvdData.stateLog.getLastStateLogForEvent(event);

                else
                    error('Unknown action execution node: %s', execNode.name);
                end

            else
                stateLogEntry = LaunchVehicleStateLogEntry.empty(1,0);
            end
        end

        function setIfCondStatusImage(app)
            stateLogEntry = app.getStateThisActionIsAppliedTo();

            if(not(isempty(stateLogEntry)))
                tf = app.action.ifCondition.evaluateConditional(stateLogEntry);

                if(tf)
                    app.IfConditionImage.ImageSource = 'greencheck.png';
                    app.IfConditionImage.Tooltip = 'Condition Satisfied';
                else
                    app.IfConditionImage.ImageSource = 'cancel.png';
                    app.IfConditionImage.Tooltip = 'Condition Not Satisfied';
                end

            else
                app.IfConditionImage.ImageSource = 'yellowalert_blackoutline.png';
                app.IfConditionImage.Tooltip = 'Condition Unknown.  Script may need to be rerun first.';
            end
        end

        function setElseIfCondRowIcons(app)
            stateLogEntry = app.getStateThisActionIsAppliedTo();

            elseIfConds = app.action.elseifConditions;
            for(i=1:length(elseIfConds))
                if(not(isempty(stateLogEntry)))
                    elseIfCond = elseIfConds(i);
    
                    tf = elseIfCond.evaluateConditional(stateLogEntry);
                    if(tf)
                        s = uistyle("Icon",'greencheck.png', 'IconAlignment','left');
                        addStyle(app.ElseIfConditionsListbox, s, 'item', i);
                    else
                        s = uistyle("Icon",'cancel.png', 'IconAlignment','left');
                        addStyle(app.ElseIfConditionsListbox, s, 'item', i);
                    end

                else
                    s = uistyle("Icon",'yellowalert_blackoutline.png', 'IconAlignment','left');
                    addStyle(app.ElseIfConditionsListbox, s, 'item', i);
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, action, lvdData, output)
            centerUIFigure(app.EditActionUIFigure);
            applySelectedThemeToApp(app);

            app.action = action;
            app.lvdData = lvdData;
            app.output = output;

            app.populateGUI();

            uiwait(app.EditActionUIFigure);
        end

        % Value changed function: ElseIfConditionsListbox
        function ElseIfConditionsListboxValueChanged(app, event)
            app.populateElseIfActionsListbox();
            app.populateElseIfCondTextArea();
            app.setDeleteActionsButtonsStates();
        end

        % Button pushed function: EditIFConditionalButton
        function EditIFConditionalButtonPushed(app, event)
            out = AppDesignerGUIOutput({AbstractActionConditional.empty(1,0), false});
            lvd_EditActionConditionalGUI_App(app.action.ifCondition, app.lvdData, out);
            conditional = out.output{1};
            tf = out.output{2};

            if(tf)
                app.action.ifCondition = conditional;
            end

            app.IfConditionalTextArea.Value = app.action.ifCondition.getConditionalString();
            app.IfConditionalTextArea.Tooltip = app.IfConditionalTextArea.Value;

            app.setIfCondStatusImage();
        end

        % Button pushed function: AddIFActionButton
        function AddIFActionButtonPushed(app, event)
            [newAction, addActionTf] = app.createNewAction();
            if(addActionTf)
                app.action.addIfAction(newAction);
                app.populateIfActionsListbox();
                app.IfActionsListBox.Value = newAction;
            end

            app.setDeleteActionsButtonsStates();
        end

        % Button pushed function: RemoveIFActionButton
        function RemoveIFActionButtonPushed(app, event)
            app.action.removeIfAction(app.IfActionsListBox.Value);
            app.populateIfActionsListbox();
            app.setDeleteActionsButtonsStates();
        end

        % Button pushed function: AddElseIfConditionalButton
        function AddElseIfConditionalButtonPushed(app, event)
            newConditional = AlwaysTrueActionCondition();
            app.action.addElseIfConditional(newConditional);

            app.populateElseIfConditionsListbox();
            app.ElseIfConditionsListbox.Value = newConditional;
            app.populateElseIfActionsListbox();
            app.populateElseIfCondTextArea();
            app.setDeleteActionsButtonsStates();
        end

        % Button pushed function: RemoveElseIfConditionalButton
        function RemoveElseIfConditionalButtonPushed(app, event)
            cond = app.ElseIfConditionsListbox.Value;
            app.action.removeElseIfConditional(cond);

            app.populateElseIfConditionsListbox();
            app.populateElseIfActionsListbox();
            app.populateElseIfCondTextArea();
            app.setDeleteActionsButtonsStates();
        end

        % Button pushed function: AddELSEIFActionButton
        function AddELSEIFActionButtonPushed(app, event)
            elseifCond = app.ElseIfConditionsListbox.Value;

            [newAction, addActionTf] = app.createNewAction();
            if(addActionTf)
                app.action.addElseIfAction(elseifCond, newAction);
                app.populateElseIfActionsListbox();
                app.ElseIfActionsListBox.Value = newAction;
            end

            app.setDeleteActionsButtonsStates();
        end

        % Button pushed function: RemoveELSEIFActionButton
        function RemoveELSEIFActionButtonPushed(app, event)
            elseifCond = app.ElseIfConditionsListbox.Value;

            app.action.removeElseIfAction(elseifCond, app.ElseIfActionsListBox.Value);
            app.populateElseIfActionsListbox();
            app.setDeleteActionsButtonsStates();
        end

        % Button pushed function: AddELSEActionButton
        function AddELSEActionButtonPushed(app, event)
            [newAction, addActionTf] = app.createNewAction();
            if(addActionTf)
                app.action.addElseAction(newAction);
                app.populateElseActionsListbox();
                app.ElseActionsListBox.Value = newAction;
            end
            app.setDeleteActionsButtonsStates();
        end

        % Button pushed function: RemoveELSEActionButton
        function RemoveELSEActionButtonPushed(app, event)
            app.action.removeElseAction(app.ElseActionsListBox.Value);
            app.populateElseActionsListbox();
            app.setDeleteActionsButtonsStates();
        end

        % Button pushed function: SaveandCloseButton
        function SaveandCloseButtonPushed(app, event)
            app.saveAndClose();
        end

        % Double-clicked callback: IfActionsListBox
        function IfActionsListBoxDoubleClicked(app, event)
            thisAction = app.IfActionsListBox.Value;
            app.editSelectedAction(thisAction);
            app.populateIfActionsListbox();
        end

        % Double-clicked callback: ElseIfActionsListBox
        function ElseIfActionsListBoxDoubleClicked(app, event)
            thisAction = app.ElseIfActionsListBox.Value;
            app.editSelectedAction(thisAction);
            app.populateElseIfActionsListbox();            
        end

        % Double-clicked callback: ElseActionsListBox
        function ElseActionsListBoxDoubleClicked(app, event)
            thisAction = app.ElseActionsListBox.Value;
            app.editSelectedAction(thisAction);
            app.populateElseActionsListbox();               
        end

        % Double-clicked callback: ElseIfConditionsListbox
        function ElseIfConditionsListboxDoubleClicked(app, event)
            app.editSelectedElseIfCondition();
            app.setElseIfCondRowIcons();
        end

        % Button pushed function: EditELSEIFConditionalButton
        function EditELSEIFConditionalButtonPushed(app, event)
            app.editSelectedElseIfCondition();
            app.setElseIfCondRowIcons();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditActionUIFigure = uifigure('Visible', 'off');
            app.EditActionUIFigure.Name = 'Edit Action';
            app.GridLayout = uigridlayout(app.EditActionUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Action';
            app.SaveandCloseButton = uibutton(app.GridLayout, 'push');
            app.SaveandCloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandCloseButtonPushed, true);
            app.SaveandCloseButton.Icon = 'save-file.png';
            app.SaveandCloseButton.Text = 'Save and Close';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.IFConditionPanel = uipanel(app.GridLayout2);
            app.IFConditionPanel.Title = 'IF Condition';
            app.GridLayout3 = uigridlayout(app.IFConditionPanel);
            app.ConditionsPanel = uipanel(app.GridLayout3);
            app.ConditionsPanel.Title = 'Conditions';
            app.GridLayout14 = uigridlayout(app.ConditionsPanel);
            app.EditIFConditionalButton = uibutton(app.GridLayout14, 'push');
            app.EditIFConditionalButton.ButtonPushedFcn = createCallbackFcn(app, @EditIFConditionalButtonPushed, true);
            app.EditIFConditionalButton.Icon = 'if.png';
            app.EditIFConditionalButton.Text = 'Edit IF Conditional';
            app.GridLayout21 = uigridlayout(app.GridLayout14);
            app.IfConditionalTextArea = uitextarea(app.GridLayout21);
            app.IfConditionalTextArea.Editable = 'off';
            app.IfConditionImage = uiimage(app.GridLayout21);
            app.ActionsPanel = uipanel(app.GridLayout3);
            app.ActionsPanel.Title = 'Actions';
            app.GridLayout15 = uigridlayout(app.ActionsPanel);
            app.IfActionsListBox = uilistbox(app.GridLayout15);
            app.IfActionsListBox.Tooltip = {'These actions are executed if the condition(s) to the left are satisfied.  No other actions are executed.'};
            app.IfActionsListBox.DoubleClickedFcn = createCallbackFcn(app, @IfActionsListBoxDoubleClicked, true);
            app.GridLayout16 = uigridlayout(app.GridLayout15);
            app.AddIFActionButton = uibutton(app.GridLayout16, 'push');
            app.AddIFActionButton.ButtonPushedFcn = createCallbackFcn(app, @AddIFActionButtonPushed, true);
            app.AddIFActionButton.Icon = 'add.png';
            app.AddIFActionButton.Tooltip = {'Add an action to be executed if the IF condition is satisified.'};
            app.AddIFActionButton.Text = 'Add Action';
            app.RemoveIFActionButton = uibutton(app.GridLayout16, 'push');
            app.RemoveIFActionButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveIFActionButtonPushed, true);
            app.RemoveIFActionButton.Icon = 'remove.png';
            app.RemoveIFActionButton.Tooltip = {'Remove the selected action.'};
            app.RemoveIFActionButton.Text = 'Remove Action';
            app.ELSEIFConditionsPanel = uipanel(app.GridLayout2);
            app.ELSEIFConditionsPanel.Title = 'ELSE IF Conditions';
            app.GridLayout11 = uigridlayout(app.ELSEIFConditionsPanel);
            app.ConditionsPanel_3 = uipanel(app.GridLayout11);
            app.ConditionsPanel_3.Title = 'Conditions';
            app.GridLayout5 = uigridlayout(app.ConditionsPanel_3);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.AddElseIfConditionalButton = uibutton(app.GridLayout7, 'push');
            app.AddElseIfConditionalButton.ButtonPushedFcn = createCallbackFcn(app, @AddElseIfConditionalButtonPushed, true);
            app.AddElseIfConditionalButton.Icon = 'add.png';
            app.AddElseIfConditionalButton.Tooltip = {'Add Else If Condition'};
            app.AddElseIfConditionalButton.Text = '';
            app.RemoveElseIfConditionalButton = uibutton(app.GridLayout7, 'push');
            app.RemoveElseIfConditionalButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveElseIfConditionalButtonPushed, true);
            app.RemoveElseIfConditionalButton.Icon = 'remove.png';
            app.RemoveElseIfConditionalButton.Tooltip = {'Remove Selected Else If Condition'};
            app.RemoveElseIfConditionalButton.Text = '';
            app.ElseIfConditionsListbox = uilistbox(app.GridLayout6);
            app.ElseIfConditionsListbox.ValueChangedFcn = createCallbackFcn(app, @ElseIfConditionsListboxValueChanged, true);
            app.ElseIfConditionsListbox.Tooltip = {'Each element here represests a set of conditions and their associated actions.  These conditions are evaluated in order from top to bottom and the actions associated with the first condition that returns true are executed.  No other actions are executed.'};
            app.ElseIfConditionsListbox.DoubleClickedFcn = createCallbackFcn(app, @ElseIfConditionsListboxDoubleClicked, true);
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.ElseIfConditionalTextArea = uitextarea(app.GridLayout8);
            app.ElseIfConditionalTextArea.Editable = 'off';
            app.EditELSEIFConditionalButton = uibutton(app.GridLayout8, 'push');
            app.EditELSEIFConditionalButton.ButtonPushedFcn = createCallbackFcn(app, @EditELSEIFConditionalButtonPushed, true);
            app.EditELSEIFConditionalButton.Icon = 'arrows.png';
            app.EditELSEIFConditionalButton.Text = 'Edit ELSE IF Conditional';
            app.ActionsPanel_3 = uipanel(app.GridLayout11);
            app.ActionsPanel_3.Title = 'Actions';
            app.GridLayout19 = uigridlayout(app.ActionsPanel_3);
            app.ElseIfActionsListBox = uilistbox(app.GridLayout19);
            app.ElseIfActionsListBox.Tooltip = {'These actions are executed if the condition(s) to the left are satified, the IF conditions are not satisfied, and all other ELSE IF conditions above this are not satisfied. '};
            app.ElseIfActionsListBox.DoubleClickedFcn = createCallbackFcn(app, @ElseIfActionsListBoxDoubleClicked, true);
            app.GridLayout20 = uigridlayout(app.GridLayout19);
            app.AddELSEIFActionButton = uibutton(app.GridLayout20, 'push');
            app.AddELSEIFActionButton.ButtonPushedFcn = createCallbackFcn(app, @AddELSEIFActionButtonPushed, true);
            app.AddELSEIFActionButton.Icon = 'add.png';
            app.AddELSEIFActionButton.Text = 'Add Action';
            app.RemoveELSEIFActionButton = uibutton(app.GridLayout20, 'push');
            app.RemoveELSEIFActionButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveELSEIFActionButtonPushed, true);
            app.RemoveELSEIFActionButton.Icon = 'remove.png';
            app.RemoveELSEIFActionButton.Tooltip = {'Remove the selected action.'};
            app.RemoveELSEIFActionButton.Text = 'Remove Action';
            app.ELSEConditionPanel = uipanel(app.GridLayout2);
            app.ELSEConditionPanel.Title = 'ELSE Condition';
            app.GridLayout4 = uigridlayout(app.ELSEConditionPanel);
            app.ActionsPanel_2 = uipanel(app.GridLayout4);
            app.ActionsPanel_2.Title = 'Actions';
            app.GridLayout17_2 = uigridlayout(app.ActionsPanel_2);
            app.ElseActionsListBox = uilistbox(app.GridLayout17_2);
            app.ElseActionsListBox.Tooltip = {'These actions are executed if none of the IF or ELSE IF conditions are satisfied.  No other actions are executed.'};
            app.ElseActionsListBox.DoubleClickedFcn = createCallbackFcn(app, @ElseActionsListBoxDoubleClicked, true);
            app.GridLayout18 = uigridlayout(app.GridLayout17_2);
            app.AddELSEActionButton = uibutton(app.GridLayout18, 'push');
            app.AddELSEActionButton.ButtonPushedFcn = createCallbackFcn(app, @AddELSEActionButtonPushed, true);
            app.AddELSEActionButton.Icon = 'add.png';
            app.AddELSEActionButton.Tooltip = {'Add an action to be executed if no IF or ELSE IF conditions are satisfied.'};
            app.AddELSEActionButton.Text = 'Add Action';
            app.RemoveELSEActionButton = uibutton(app.GridLayout18, 'push');
            app.RemoveELSEActionButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveELSEActionButtonPushed, true);
            app.RemoveELSEActionButton.Icon = 'remove.png';
            app.RemoveELSEActionButton.Tooltip = {'Remove the selected action.'};
            app.RemoveELSEActionButton.Text = 'Remove Action';
            % Show the figure after all components are created
            app.EditActionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditConditionalActionGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditActionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditActionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditActionUIFigure)
        end
    end
end