classdef lvd_EditActionSetQuatInterpSteeringModelGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditActionSetQuatInterpSteeringModelGUI  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        uipanel1                   matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        angle3Panel                matlab.ui.container.Panel
        GridLayout9                matlab.ui.container.GridLayout
        angle3FinalUbText          matlab.ui.control.EditField
        angle3FinalLbText          matlab.ui.control.EditField
        angle3FinalOptCheckbox     matlab.ui.control.CheckBox
        text21                     matlab.ui.control.Label
        angle3FinalText            matlab.ui.control.EditField
        text20                     matlab.ui.control.Label
        angle3InitUbText           matlab.ui.control.EditField
        angle3InitLbText           matlab.ui.control.EditField
        angle3InitOptCheckbox      matlab.ui.control.CheckBox
        text19                     matlab.ui.control.Label
        angle3InitText             matlab.ui.control.EditField
        text18                     matlab.ui.control.Label
        angle2Panel                matlab.ui.container.Panel
        GridLayout8                matlab.ui.container.GridLayout
        angle2FinalUbText          matlab.ui.control.EditField
        angle2FinalLbText          matlab.ui.control.EditField
        angle2FinalOptCheckbox     matlab.ui.control.CheckBox
        text15                     matlab.ui.control.Label
        angle2FinalText            matlab.ui.control.EditField
        text14                     matlab.ui.control.Label
        angle2InitUbText           matlab.ui.control.EditField
        angle2InitLbText           matlab.ui.control.EditField
        angle2InitOptCheckbox      matlab.ui.control.CheckBox
        text13                     matlab.ui.control.Label
        angle2InitText             matlab.ui.control.EditField
        text12                     matlab.ui.control.Label
        angle1Panel                matlab.ui.container.Panel
        GridLayout7                matlab.ui.container.GridLayout
        angle1FinalUbText          matlab.ui.control.EditField
        angle1FinalLbText          matlab.ui.control.EditField
        angle1FinalOptCheckbox     matlab.ui.control.CheckBox
        text8                      matlab.ui.control.Label
        angle1FinalText            matlab.ui.control.EditField
        angle1InitUbText           matlab.ui.control.EditField
        angle1InitLbText           matlab.ui.control.EditField
        angle1InitOptCheckbox      matlab.ui.control.CheckBox
        text6                      matlab.ui.control.Label
        angle1InitText             matlab.ui.control.EditField
        text7                      matlab.ui.control.Label
        text5                      matlab.ui.control.Label
        uipanel6                   matlab.ui.container.Panel
        GridLayout6                matlab.ui.container.GridLayout
        tDurUbText                 matlab.ui.control.EditField
        tDurLbText                 matlab.ui.control.EditField
        tDurOptCheckbox            matlab.ui.control.CheckBox
        text29                     matlab.ui.control.Label
        tDurText                   matlab.ui.control.EditField
        text28                     matlab.ui.control.Label
        GridLayout5                matlab.ui.container.GridLayout
        controlFrameCombo          matlab.ui.control.DropDown
        text27                     matlab.ui.control.Label
        GridLayout4                matlab.ui.container.GridLayout
        setBaseFrameOptionsButton  matlab.ui.control.Button
        baseFrameCombo             matlab.ui.control.DropDown
        text26                     matlab.ui.control.Label
        angleContCheckbox          matlab.ui.control.CheckBox
        titleLabel                 matlab.ui.control.Label
        GridLayout2                matlab.ui.container.GridLayout
        cancelButton               matlab.ui.control.Button
        saveAndCloseButton         matlab.ui.control.Button
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function bodyInfo = getSelectedBodyInfo(app, handles)
            baseFrame = getappdata(handles.lvd_EditActionSetQuatInterpSteeringModelGUI,'baseFrame');
            bodyInfo = baseFrame.getOriginBody();

            if(isempty(bodyInfo))
                lv = getappdata(handles.lvd_EditActionSetQuatInterpSteeringModelGUI,'lv');
                lvdData = lv.lvdData;

                bodyInfo = LvdData.getDefaultInitialBodyInfo(lvdData.celBodyData);
            end
        end

        function populateGUI(app, handles, steeringModel, lv, useContinuity)
            handles.baseFrameCombo.String = ReferenceFrameEnum.getListBoxStr();

            if(steeringModel.usesRefFrame())
                handles.baseFrameCombo.Enable = 'on';
                handles.setBaseFrameOptionsButton.Enable = 'on';
                baseFrame = steeringModel.getRefFrame();

                if(isempty(baseFrame))
                    baseFrame = lv.lvdData.getDefaultInitialBodyInfo(lv.lvdData.celBodyData).getBodyCenteredInertialFrame();
                    steeringModel.setRefFrame(baseFrame);
                end
            else
                handles.baseFrameCombo.Enable = 'off';
                handles.setBaseFrameOptionsButton.Enable = 'off';
                baseFrame = lv.lvdData.getDefaultInitialBodyInfo(lv.lvdData.celBodyData).getBodyCenteredInertialFrame();
            end

            handles.baseFrameCombo.Value = ReferenceFrameEnum.getIndForName(baseFrame.typeEnum.name);
            setappdata(handles.lvd_EditActionSetQuatInterpSteeringModelGUI, 'baseFrame', baseFrame);

            handles.controlFrameCombo.String = ControlFramesEnum.getListBoxStr();

            if(steeringModel.usesControlFrame())
                handles.controlFrameCombo.Enable = 'on';
                controlFrame = steeringModel.getControlFrame();

                if(isempty(controlFrame))
                    controlFrame = NedControlFrame();
                    steeringModel.setControlFrame(controlFrame);
                end

                handles.controlFrameCombo.Value = ControlFramesEnum.getIndForName(controlFrame.enum.name);

            else
                handles.controlFrameCombo.Enable = 'off';
                handles.controlFrameCombo.Value = ControlFramesEnum.getIndForName(ControlFramesEnum.NedFrame.name);
            end

            [angle1Name, angle2Name, angle3Name] = steeringModel.getAngleNames();
            set(handles.angle1Panel,'Title',sprintf('%s', angle1Name));
            set(handles.angle2Panel,'Title',sprintf('%s', angle2Name));
            set(handles.angle3Panel,'Title',sprintf('%s', angle3Name));

            tDur = steeringModel.getDuration();
            [gamma0, beta0, alpha0] = steeringModel.getInitAngles();
            [gamma1, beta1, alpha1] = steeringModel.getFinalAngles();

            set(handles.tDurText,'String',fullAccNum2Str(tDur));

            set(handles.angle1InitText,'String',fullAccNum2Str(rad2deg(gamma0)));
            set(handles.angle1FinalText,'String',fullAccNum2Str(rad2deg(gamma1)));

            set(handles.angle2InitText,'String',fullAccNum2Str(rad2deg(beta0)));
            set(handles.angle2FinalText,'String',fullAccNum2Str(rad2deg(beta1)));

            set(handles.angle3InitText,'String',fullAccNum2Str(rad2deg(alpha0)));
            set(handles.angle3FinalText,'String',fullAccNum2Str(rad2deg(alpha1)));

            angleCont = steeringModel.getContinuityTerms();
            if(useContinuity)
                handles.angleContCheckbox.Enable = 'on';
            else
                handles.angleContCheckbox.Enable = 'off';
            end
            handles.angleContCheckbox.Value = double(angleCont);
            
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            angleContCheckbox_Callback(app, evt);

            optVar = steeringModel.getExistingOptVar();
            if(isempty(optVar))
                useTf = false([1,7]);
                lb = zeros(size(useTf));
                ub = zeros(size(useTf));
            else
                useTf = optVar.getUseTfForVariable();
                optVar.setUseTfForVariable(true(size(useTf)));
                [lb, ub] = optVar.getBndsForVariable();
                optVar.setUseTfForVariable(useTf);
            end

            set(handles.tDurOptCheckbox,'Value',useTf(1));

            set(handles.angle1InitOptCheckbox,'Value',(useTf(2)));
            set(handles.angle1FinalOptCheckbox,'Value',(useTf(3)));

            set(handles.angle2InitOptCheckbox,'Value',(useTf(4)));
            set(handles.angle2FinalOptCheckbox,'Value',(useTf(5)));

            set(handles.angle3InitOptCheckbox,'Value',(useTf(6)));
            set(handles.angle3FinalOptCheckbox,'Value',(useTf(7)));

            tDurOptCheckbox_Callback(app, evt);

            angle1InitOptCheckbox_Callback(app, evt);
            angle2InitOptCheckbox_Callback(app, evt);
            angle3InitOptCheckbox_Callback(app, evt);

            angle1FinalOptCheckbox_Callback(app, evt);
            angle2FinalOptCheckbox_Callback(app, evt);
            angle3FinalOptCheckbox_Callback(app, evt);

            %LB
            set(handles.tDurLbText,'String',fullAccNum2Str(lb(1)));

            set(handles.angle1InitLbText,'String',fullAccNum2Str(rad2deg(lb(2))));
            set(handles.angle1FinalLbText,'String',fullAccNum2Str(rad2deg(lb(3))));

            set(handles.angle2InitLbText,'String',fullAccNum2Str(rad2deg(lb(4))));
            set(handles.angle2FinalLbText,'String',fullAccNum2Str(rad2deg(lb(5))));

            set(handles.angle3InitLbText,'String',fullAccNum2Str(rad2deg(lb(6))));
            set(handles.angle3FinalLbText,'String',fullAccNum2Str(rad2deg(lb(7))));

            %UB
            set(handles.tDurUbText,'String',fullAccNum2Str(ub(1)));

            set(handles.angle1InitUbText,'String',fullAccNum2Str(rad2deg(ub(2))));
            set(handles.angle1FinalUbText,'String',fullAccNum2Str(rad2deg(ub(3))));

            set(handles.angle2InitUbText,'String',fullAccNum2Str(rad2deg(ub(4))));
            set(handles.angle2FinalUbText,'String',fullAccNum2Str(rad2deg(ub(5))));

            set(handles.angle3InitUbText,'String',fullAccNum2Str(rad2deg(ub(6))));
            set(handles.angle3FinalUbText,'String',fullAccNum2Str(rad2deg(ub(7))));
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            steeringModel = getappdata(handles.lvd_EditActionSetQuatInterpSteeringModelGUI,'steeringModel');

            [angle1Name, angle2Name, angle3Name] = steeringModel.getAngleNames();

            %Duration
            tDur = str2double(get(handles.tDurText,'String'));
            enteredStr = get(handles.tDurText,'String');
            numberName = sprintf('Rotation Duration');
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(tDur, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 1
            angle1Init = str2double(get(handles.angle1InitText,'String'));
            enteredStr = get(handles.angle1InitText,'String');
            numberName = sprintf('%s Initial Value', angle1Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle1Init, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle1Final = str2double(get(handles.angle1FinalText,'String'));
            enteredStr = get(handles.angle1FinalText,'String');
            numberName = sprintf('%s Final Value', angle1Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle1Final, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 2
            angle2Init = str2double(get(handles.angle2InitText,'String'));
            enteredStr = get(handles.angle2InitText,'String');
            numberName = sprintf('%s Initial Value', angle2Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle2Init, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle2Final = str2double(get(handles.angle2FinalText,'String'));
            enteredStr = get(handles.angle2FinalText,'String');
            numberName = sprintf('%s Final Value', angle2Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle2Final, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 3
            angle3Init = str2double(get(handles.angle3InitText,'String'));
            enteredStr = get(handles.angle3InitText,'String');
            numberName = sprintf('%s Initial Value', angle3Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle3Init, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle3Final = str2double(get(handles.angle3FinalText,'String'));
            enteredStr = get(handles.angle3FinalText,'String');
            numberName = sprintf('%s Final Value', angle3Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle3Final, numberName, lb, ub, isInt, errMsg, enteredStr);

            %%%%%Bounds
            %Angle 1 Const
            angle1InitLB = str2double(get(handles.angle1InitLbText,'String'));
            enteredStr = get(handles.angle1InitLbText,'String');
            numberName = sprintf('%s Initial Value Lower Bound', angle1Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle1InitLB, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle1InitUB = str2double(get(handles.angle1InitUbText,'String'));
            enteredStr = get(handles.angle1InitUbText,'String');
            numberName = sprintf('%s Initial Value Upper Bound', angle1Name);
            lb = angle1InitLB;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle1InitUB, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 1 Linear
            angle1FinalLB = str2double(get(handles.angle1FinalLbText,'String'));
            enteredStr = get(handles.angle1FinalLbText,'String');
            numberName = sprintf('%s Final Value Lower Bound', angle1Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle1FinalLB, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle1FinalUB = str2double(get(handles.angle1FinalUbText,'String'));
            enteredStr = get(handles.angle1FinalUbText,'String');
            numberName = sprintf('%s Final Value Upper Bound', angle1Name);
            lb = angle1FinalLB;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle1FinalUB, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 2 Const
            angle2InitLB = str2double(get(handles.angle2InitLbText,'String'));
            enteredStr = get(handles.angle2InitLbText,'String');
            numberName = sprintf('%s Initial Value Lower Bound', angle2Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle2InitLB, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle2InitUB = str2double(get(handles.angle2InitUbText,'String'));
            enteredStr = get(handles.angle2InitUbText,'String');
            numberName = sprintf('%s Initial Value Upper Bound', angle2Name);
            lb = angle2InitLB;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle2InitUB, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 2 Linear
            angle2FinalLB = str2double(get(handles.angle2FinalLbText,'String'));
            enteredStr = get(handles.angle2FinalLbText,'String');
            numberName = sprintf('%s Final Value Lower Bound', angle2Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle2FinalLB, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle2FinalUB = str2double(get(handles.angle2FinalUbText,'String'));
            enteredStr = get(handles.angle2FinalUbText,'String');
            numberName = sprintf('%s Final Value Upper Bound', angle2Name);
            lb = angle2FinalLB;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle2FinalUB, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 3 Const
            angle3InitLB = str2double(get(handles.angle3InitLbText,'String'));
            enteredStr = get(handles.angle3InitLbText,'String');
            numberName = sprintf('%s Initial Value Lower Bound', angle3Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle3InitLB, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle3InitUB = str2double(get(handles.angle3InitUbText,'String'));
            enteredStr = get(handles.angle3InitUbText,'String');
            numberName = sprintf('%s Initial Value Upper Bound', angle3Name);
            lb = angle3InitLB;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle3InitUB, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 2 Linear
            angle3FinalLB = str2double(get(handles.angle3FinalLbText,'String'));
            enteredStr = get(handles.angle3FinalLbText,'String');
            numberName = sprintf('%s Final Value Lower Bound', angle3Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle3FinalLB, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle3FinalUB = str2double(get(handles.angle3FinalUbText,'String'));
            enteredStr = get(handles.angle3FinalUbText,'String');
            numberName = sprintf('%s Final Value Upper Bound', angle3Name);
            lb = angle3FinalLB;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle3FinalUB, numberName, lb, ub, isInt, errMsg, enteredStr);
        end


        function saveAndClose(app, handles)
            lv = getappdata(handles.lvd_EditActionSetQuatInterpSteeringModelGUI,'lv');
            lvdData = lv.lvdData;

            steeringModel = getappdata(handles.lvd_EditActionSetQuatInterpSteeringModelGUI,'steeringModel');

            optVar = steeringModel.getExistingOptVar();
            if(not(isempty(optVar))) %need to remove existing var if it exists
                lvdData.optimizer.vars.removeVariable(optVar);
            end

            if(steeringModel.usesRefFrame())
                steeringModel.setRefFrame(getappdata(handles.lvd_EditActionSetQuatInterpSteeringModelGUI,'baseFrame'));
            end

            if(steeringModel.usesControlFrame())
                contents = cellstr(get(handles.controlFrameCombo,'String'));
                cFrameName = contents{get(handles.controlFrameCombo,'Value')};
                cFrameEnum = ControlFramesEnum.getEnumForListboxStr(cFrameName);
                cFrame = ControlFramesEnum.getControlFrameForEnum(cFrameEnum);

                steeringModel.setControlFrame(cFrame);
            end

            %Set Steering Terms
            tDur = str2double(get(handles.tDurText,'String'));

            angle1Init = deg2rad(str2double(get(handles.angle1InitText,'String')));
            angle1Final = deg2rad(str2double(get(handles.angle1FinalText,'String')));

            angle2Init = deg2rad(str2double(get(handles.angle2InitText,'String')));
            angle2Final = deg2rad(str2double(get(handles.angle2FinalText,'String')));

            angle3Init = deg2rad(str2double(get(handles.angle3InitText,'String')));
            angle3Final = deg2rad(str2double(get(handles.angle3FinalText,'String')));

            steeringModel.setDuration(tDur);

            steeringModel.setInitAngles(angle1Init, angle2Init, angle3Init);
            steeringModel.setFinalAngles(angle1Final, angle2Final, angle3Final);

            contTf = logical(handles.angleContCheckbox.Value);
            steeringModel.setContinuityTerms(contTf);

            %Set Opt T/F
            useTf(1) = get(handles.tDurOptCheckbox,'Value');

            useTf(2) = get(handles.angle1InitOptCheckbox,'Value');
            useTf(3) = get(handles.angle1FinalOptCheckbox,'Value');

            useTf(4) = get(handles.angle2InitOptCheckbox,'Value');
            useTf(5) = get(handles.angle2FinalOptCheckbox,'Value');

            useTf(6) = get(handles.angle3InitOptCheckbox,'Value');
            useTf(7) = get(handles.angle3FinalOptCheckbox,'Value');

            optVar = steeringModel.getNewOptVar();

            optVar.setUseTfForVariable(useTf);

            %LB
            lb(1) = str2double(handles.tDurLbText.String);

            lb(2) = deg2rad(str2double(get(handles.angle1InitLbText,'String')));
            lb(3) = deg2rad(str2double(get(handles.angle1FinalLbText,'String')));

            lb(4) = deg2rad(str2double(get(handles.angle2InitLbText,'String')));
            lb(5) = deg2rad(str2double(get(handles.angle2FinalLbText,'String')));

            lb(6) = deg2rad(str2double(get(handles.angle3InitLbText,'String')));
            lb(7) = deg2rad(str2double(get(handles.angle3FinalLbText,'String')));

            %UB
            ub(1) = str2double(handles.tDurUbText.String);

            ub(2) = deg2rad(str2double(get(handles.angle1InitUbText,'String')));
            ub(3) = deg2rad(str2double(get(handles.angle1FinalUbText,'String')));

            ub(4) = deg2rad(str2double(get(handles.angle2InitUbText,'String')));
            ub(5) = deg2rad(str2double(get(handles.angle2FinalUbText,'String')));

            ub(6) = deg2rad(str2double(get(handles.angle3InitUbText,'String')));
            ub(7) = deg2rad(str2double(get(handles.angle3FinalUbText,'String')));

            optVar.setUseTfForVariable(true(size(lb))); %need this to get the full lb/set in there
            optVar.setBndsForVariable(lb, ub);
            optVar.setUseTfForVariable(useTf);

            lvdData.optimizer.vars.addVariable(optVar);

            app.output.output = {true, steeringModel};
            close(handles.lvd_EditActionSetQuatInterpSteeringModelGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditActionSetQuatInterpSteeringModelGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditActionSetQuatInterpSteeringModelGUI (see VARARGIN)

            % Choose default command line output for lvd_EditActionSetQuatInterpSteeringModelGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            steeringModel = varargin{1};
            setappdata(hObject,'steeringModel',steeringModel);

            lv = varargin{2};
            setappdata(hObject,'lv',lv);

            useContinuity = varargin{3};
            setappdata(hObject,'useContinuity',useContinuity);
            
            app.output = varargin{4};

            populateGUI(app, handles, steeringModel, lv, useContinuity);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditActionSetQuatInterpSteeringModelGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditActionSetQuatInterpSteeringModelGUI);
        end

        % Value changed function: angle1FinalLbText
        function angle1FinalLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle1FinalLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle1FinalLbText as text
            %        str2double(get(hObject,'String')) returns contents of angle1FinalLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle1FinalOptCheckbox
        function angle1FinalOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.angle1FinalOptCheckbox;
            
            % hObject    handle to angle1FinalOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of angle1FinalOptCheckbox
            if(get(hObject,'Value')==1)
                set(handles.angle1FinalLbText,'Enable','on');
                set(handles.angle1FinalUbText,'Enable','on');
            else
                set(handles.angle1FinalLbText,'Enable','off');
                set(handles.angle1FinalUbText,'Enable','off');
            end
        end

        % Value changed function: angle1FinalText
        function angle1FinalText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle1FinalText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle1FinalText as text
            %        str2double(get(hObject,'String')) returns contents of angle1FinalText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle1FinalUbText
        function angle1FinalUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle1FinalUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle1FinalUbText as text
            %        str2double(get(hObject,'String')) returns contents of angle1FinalUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle1InitLbText
        function angle1InitLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle1InitLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle1InitLbText as text
            %        str2double(get(hObject,'String')) returns contents of angle1InitLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle1InitOptCheckbox
        function angle1InitOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.angle1InitOptCheckbox;
            
            % hObject    handle to angle1InitOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of angle1InitOptCheckbox
            if(get(hObject,'Value')==1)
                set(handles.angle1InitLbText,'Enable','on');
                set(handles.angle1InitUbText,'Enable','on');
            else
                set(handles.angle1InitLbText,'Enable','off');
                set(handles.angle1InitUbText,'Enable','off');
            end
        end

        % Value changed function: angle1InitText
        function angle1InitText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle1InitText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle1InitText as text
            %        str2double(get(hObject,'String')) returns contents of angle1InitText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle1InitUbText
        function angle1InitUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle1InitUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle1InitUbText as text
            %        str2double(get(hObject,'String')) returns contents of angle1InitUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle2FinalLbText
        function angle2FinalLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle2FinalLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle2FinalLbText as text
            %        str2double(get(hObject,'String')) returns contents of angle2FinalLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle2FinalOptCheckbox
        function angle2FinalOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.angle2FinalOptCheckbox;
            
            % hObject    handle to angle2FinalOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of angle2FinalOptCheckbox
            if(get(hObject,'Value')==1)
                set(handles.angle2FinalLbText,'Enable','on');
                set(handles.angle2FinalUbText,'Enable','on');
            else
                set(handles.angle2FinalLbText,'Enable','off');
                set(handles.angle2FinalUbText,'Enable','off');
            end
        end

        % Value changed function: angle2FinalText
        function angle2FinalText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle2FinalText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle2FinalText as text
            %        str2double(get(hObject,'String')) returns contents of angle2FinalText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle2FinalUbText
        function angle2FinalUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle2FinalUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle2FinalUbText as text
            %        str2double(get(hObject,'String')) returns contents of angle2FinalUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle2InitLbText
        function angle2InitLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle2InitLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle2InitLbText as text
            %        str2double(get(hObject,'String')) returns contents of angle2InitLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle2InitOptCheckbox
        function angle2InitOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.angle2InitOptCheckbox;
            
            % hObject    handle to angle2InitOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of angle2InitOptCheckbox
            if(get(hObject,'Value')==1)
                set(handles.angle2InitLbText,'Enable','on');
                set(handles.angle2InitUbText,'Enable','on');
            else
                set(handles.angle2InitLbText,'Enable','off');
                set(handles.angle2InitUbText,'Enable','off');
            end
        end

        % Value changed function: angle2InitText
        function angle2InitText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle2InitText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle2InitText as text
            %        str2double(get(hObject,'String')) returns contents of angle2InitText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle2InitUbText
        function angle2InitUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle2InitUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle2InitUbText as text
            %        str2double(get(hObject,'String')) returns contents of angle2InitUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle3FinalLbText
        function angle3FinalLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle3FinalLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle3FinalLbText as text
            %        str2double(get(hObject,'String')) returns contents of angle3FinalLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle3FinalOptCheckbox
        function angle3FinalOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.angle3FinalOptCheckbox;
            
            % hObject    handle to angle3FinalOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of angle3FinalOptCheckbox
            if(get(hObject,'Value')==1)
                set(handles.angle3FinalLbText,'Enable','on');
                set(handles.angle3FinalUbText,'Enable','on');
            else
                set(handles.angle3FinalLbText,'Enable','off');
                set(handles.angle3FinalUbText,'Enable','off');
            end
        end

        % Value changed function: angle3FinalText
        function angle3FinalText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle3FinalText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle3FinalText as text
            %        str2double(get(hObject,'String')) returns contents of angle3FinalText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle3FinalUbText
        function angle3FinalUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle3FinalUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle3FinalUbText as text
            %        str2double(get(hObject,'String')) returns contents of angle3FinalUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle3InitLbText
        function angle3InitLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle3InitLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle3InitLbText as text
            %        str2double(get(hObject,'String')) returns contents of angle3InitLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle3InitOptCheckbox
        function angle3InitOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.angle3InitOptCheckbox;
            
            % hObject    handle to angle3InitOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of angle3InitOptCheckbox
            if(get(hObject,'Value')==1)
                set(handles.angle3InitLbText,'Enable','on');
                set(handles.angle3InitUbText,'Enable','on');
            else
                set(handles.angle3InitLbText,'Enable','off');
                set(handles.angle3InitUbText,'Enable','off');
            end
        end

        % Value changed function: angle3InitText
        function angle3InitText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle3InitText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle3InitText as text
            %        str2double(get(hObject,'String')) returns contents of angle3InitText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle3InitUbText
        function angle3InitUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle3InitUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle3InitUbText as text
            %        str2double(get(hObject,'String')) returns contents of angle3InitUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angleContCheckbox
        function angleContCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.angleContCheckbox;
            
            % hObject    handle to angleContCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of angleContCheckbox
            if(get(hObject,'Value'))
                handles.angle1InitText.Enable = 'off';
                handles.angle2InitText.Enable = 'off';
                handles.angle3InitText.Enable = 'off';

                handles.angle1InitOptCheckbox.Enable = 'off';
                handles.angle2InitOptCheckbox.Enable = 'off';
                handles.angle3InitOptCheckbox.Enable = 'off';

                handles.angle1InitOptCheckbox.Value = 0;
                handles.angle2InitOptCheckbox.Value = 0;
                handles.angle3InitOptCheckbox.Value = 0;

%                 evt = matlab.ui.eventdata.ValueChangedData(0, 0);
%                 angle1InitOptCheckbox_Callback(handles.angle1InitOptCheckbox, [], handles);
%                 angle2InitOptCheckbox_Callback(handles.angle2InitOptCheckbox, [], handles);
%                 angle3InitOptCheckbox_Callback(handles.angle3InitOptCheckbox, [], handles);
            else
                handles.angle1InitText.Enable = 'on';
                handles.angle2InitText.Enable = 'on';
                handles.angle3InitText.Enable = 'on';

                handles.angle1InitOptCheckbox.Enable = 'on';
                handles.angle2InitOptCheckbox.Enable = 'on';
                handles.angle3InitOptCheckbox.Enable = 'on';
            end
            
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            angle1InitOptCheckbox_Callback(app, evt);
            angle2InitOptCheckbox_Callback(app, evt);
            angle3InitOptCheckbox_Callback(app, evt);
        end

        % Value changed function: baseFrameCombo
        function baseFrameCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to baseFrameCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns baseFrameCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from baseFrameCombo
            lv = getappdata(handles.lvd_EditActionSetQuatInterpSteeringModelGUI,'lv');
            lvdData = lv.lvdData;

            contents = cellstr(get(handles.baseFrameCombo,'String'));
            selFrameType = contents{get(handles.baseFrameCombo,'Value')};
            refFrameEnum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);

            switch refFrameEnum
                case ReferenceFrameEnum.BodyCenteredInertial
                    bodyInfo = getSelectedBodyInfo(app, handles);
                    newFrame = bodyInfo.getBodyCenteredInertialFrame();

                case ReferenceFrameEnum.BodyFixedRotating
                    bodyInfo = getSelectedBodyInfo(app, handles);
                    newFrame = bodyInfo.getBodyFixedFrame();

                case ReferenceFrameEnum.TwoBodyRotating
                    bodyInfo = getSelectedBodyInfo(app, handles);
                    if(not(isempty(bodyInfo.childrenBodyInfo)))
                        primaryBody = bodyInfo;
                        secondaryBody = bodyInfo.childrenBodyInfo(1);
                    else
                        primaryBody = bodyInfo.getParBodyInfo();
                        secondaryBody = bodyInfo;
                    end

                    originPt = TwoBodyRotatingFrameOriginEnum.Primary;

                    newFrame = TwoBodyRotatingFrame(primaryBody, secondaryBody, originPt, bodyInfo.celBodyData);

                case ReferenceFrameEnum.UserDefined
                    numFrames = lvdData.geometry.refFrames.getNumRefFrames();
                    if(numFrames >= 1)
                        geometricFrame = lvdData.geometry.refFrames.getRefFrameAtInd(1);
                        newFrame = UserDefinedGeometricFrame(geometricFrame, lvdData);
                    else
                        bodyInfo = getSelectedBodyInfo(app, handles);
                        newFrame = bodyInfo.getBodyCenteredInertialFrame();
%                         warndlg('There are no geometric frames available.  A body-centered inertial frame will be selected instead.');
                        uialert(app.lvd_EditActionSetQuatInterpSteeringModelGUI, 'There are no geometric frames available.  A body-centered inertial frame will be selected instead.', 'No Frame', "Icon","warning");
                    end

                otherwise
                    error('Unknown reference frame type: %s', string(refFrameEnum));
            end

            if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
                refFrameEnum = newFrame.typeEnum;
                handles.baseFrameCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
            end

            setappdata(handles.lvd_EditActionSetQuatInterpSteeringModelGUI, 'baseFrame', newFrame);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            steeringModel = getappdata(handles.lvd_EditActionSetQuatInterpSteeringModelGUI,'steeringModel');
            app.output.output = {false, steeringModel};

            close(handles.lvd_EditActionSetQuatInterpSteeringModelGUI);
        end

        % Value changed function: controlFrameCombo
        function controlFrameCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to controlFrameCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns controlFrameCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from controlFrameCombo
            contents = cellstr(get(hObject,'String'));
            listBoxStr = contents{get(hObject,'Value')};

            enum = ControlFramesEnum.getEnumForListboxStr(listBoxStr);
            angleNames = enum.angleNames;

            set(handles.angle1Panel,'Title',sprintf('%s', angleNames{1}));
            set(handles.angle2Panel,'Title',sprintf('%s', angleNames{2}));
            set(handles.angle3Panel,'Title',sprintf('%s', angleNames{3}));
        end

        % Window key press function: 
        % lvd_EditActionSetQuatInterpSteeringModelGUI
        function lvd_EditActionSetQuatInterpSteeringModelGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditActionSetQuatInterpSteeringModelGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditActionSetQuatInterpSteeringModelGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
%                 uiresume(handles.lvd_EditActionSetQuatInterpSteeringModelGUI);
                app.saveAndClose(handles);
            else
%                 msgbox(errMsg,'Errors were found while editing the steering model.','error');
                uialert(app.lvd_EditActionSetQuatInterpSteeringModelGUI, errMsg, 'Input Errors', "Icon","error");
            end
        end

        % Button pushed function: setBaseFrameOptionsButton
        function setBaseFrameOptionsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to setBaseFrameOptionsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            baseFrame = getappdata(handles.lvd_EditActionSetQuatInterpSteeringModelGUI, 'baseFrame');
            newFrame = baseFrame.editFrameDialogUI(EditReferenceFrameContextEnum.ForSteering);

            setappdata(handles.lvd_EditActionSetQuatInterpSteeringModelGUI, 'baseFrame', newFrame);
        end

        % Value changed function: tDurLbText
        function tDurLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to tDurLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of tDurLbText as text
            %        str2double(get(hObject,'String')) returns contents of tDurLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: tDurOptCheckbox
        function tDurOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.tDurOptCheckbox;
            
            % hObject    handle to tDurOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of tDurOptCheckbox
            if(get(hObject,'Value')==1)
                set(handles.tDurLbText,'Enable','on');
                set(handles.tDurUbText,'Enable','on');
            else
                set(handles.tDurLbText,'Enable','off');
                set(handles.tDurUbText,'Enable','off');
            end
        end

        % Value changed function: tDurText
        function tDurText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to tDurText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of tDurText as text
            %        str2double(get(hObject,'String')) returns contents of tDurText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: tDurUbText
        function tDurUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to tDurUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of tDurUbText as text
            %        str2double(get(hObject,'String')) returns contents of tDurUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditActionSetQuatInterpSteeringModelGUI = uifigure('Visible', 'off');
            app.lvd_EditActionSetQuatInterpSteeringModelGUI.Name = 'Edit Steering Model';
            app.lvd_EditActionSetQuatInterpSteeringModelGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditActionSetQuatInterpSteeringModelGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditActionSetQuatInterpSteeringModelGUI_WindowKeyPressFcn, true);
            app.lvd_EditActionSetQuatInterpSteeringModelGUI.Tag = 'lvd_EditActionSetQuatInterpSteeringModelGUI';
            app.GridLayout = uigridlayout(app.lvd_EditActionSetQuatInterpSteeringModelGUI);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.titleLabel = uilabel(app.GridLayout);
            app.titleLabel.Tag = 'titleLabel';
            app.titleLabel.Text = 'Edit Steering Model';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.angleContCheckbox = uicheckbox(app.GridLayout3);
            app.angleContCheckbox.ValueChangedFcn = createCallbackFcn(app, @angleContCheckbox_Callback, true);
            app.angleContCheckbox.Tag = 'angleContCheckbox';
            app.angleContCheckbox.Tooltip = 'Instead of selecting the initial values, selecting this will carry the value of each angle into this model so that all angles are continuous in time.';
            app.angleContCheckbox.Text = 'Preserve Angle Continuity at Start of This Steering Model?';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.text26 = uilabel(app.GridLayout4);
            app.text26.Tag = 'text26';
            app.text26.Text = 'Base Frame';
            app.baseFrameCombo = uidropdown(app.GridLayout4);
            app.baseFrameCombo.Items = {'Pop-up Menu'};
            app.baseFrameCombo.ValueChangedFcn = createCallbackFcn(app, @baseFrameCombo_Callback, true);
            app.baseFrameCombo.Tag = 'baseFrameCombo';
            app.baseFrameCombo.Value = 'Pop-up Menu';
            app.setBaseFrameOptionsButton = uibutton(app.GridLayout4, 'push');
            app.setBaseFrameOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @setBaseFrameOptionsButton_Callback, true);
            app.setBaseFrameOptionsButton.Tag = 'setBaseFrameOptionsButton';
            app.setBaseFrameOptionsButton.Icon = 'reference_frame.png';
            app.setBaseFrameOptionsButton.Text = 'Set Frame Options';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.text27 = uilabel(app.GridLayout5);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Control Frame';
            app.controlFrameCombo = uidropdown(app.GridLayout5);
            app.controlFrameCombo.Items = {'Pop-up Menu'};
            app.controlFrameCombo.ValueChangedFcn = createCallbackFcn(app, @controlFrameCombo_Callback, true);
            app.controlFrameCombo.Tag = 'controlFrameCombo';
            app.controlFrameCombo.Value = 'Pop-up Menu';
            app.uipanel6 = uipanel(app.GridLayout3);
            app.uipanel6.Title = 'Rotation Duration';
            app.uipanel6.Tag = 'uipanel6';
            app.GridLayout6 = uigridlayout(app.uipanel6);
            app.text28 = uilabel(app.GridLayout6);
            app.text28.Tag = 'text28';
            app.text28.Text = 'Rot. Duration';
            app.tDurText = uieditfield(app.GridLayout6, 'text');
            app.tDurText.ValueChangedFcn = createCallbackFcn(app, @tDurText_Callback, true);
            app.tDurText.Tag = 'tDurText';
            app.tDurText.Value = '0.0';
            app.text29 = uilabel(app.GridLayout6);
            app.text29.Tag = 'text29';
            app.text29.Text = 'sec';
            app.tDurOptCheckbox = uicheckbox(app.GridLayout6);
            app.tDurOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @tDurOptCheckbox_Callback, true);
            app.tDurOptCheckbox.Tag = 'tDurOptCheckbox';
            app.tDurOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.tDurOptCheckbox.Text = 'Opt?';
            app.tDurOptCheckbox.Value = true;
            app.tDurLbText = uieditfield(app.GridLayout6, 'text');
            app.tDurLbText.ValueChangedFcn = createCallbackFcn(app, @tDurLbText_Callback, true);
            app.tDurLbText.Tag = 'tDurLbText';
            app.tDurLbText.Tooltip = 'Parameter lower bound.';
            app.tDurLbText.Value = '0.0';
            app.tDurUbText = uieditfield(app.GridLayout6, 'text');
            app.tDurUbText.ValueChangedFcn = createCallbackFcn(app, @tDurUbText_Callback, true);
            app.tDurUbText.Tag = 'tDurUbText';
            app.tDurUbText.Tooltip = 'Parameter upper bound.';
            app.tDurUbText.Value = '0.0';
            app.angle1Panel = uipanel(app.GridLayout3);
            app.angle1Panel.Title = '<name1> Angle';
            app.angle1Panel.Tag = 'angle1Panel';
            app.GridLayout7 = uigridlayout(app.angle1Panel);
            app.text5 = uilabel(app.GridLayout7);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Initial Value';
            app.text7 = uilabel(app.GridLayout7);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Final Value';
            app.angle1InitText = uieditfield(app.GridLayout7, 'text');
            app.angle1InitText.ValueChangedFcn = createCallbackFcn(app, @angle1InitText_Callback, true);
            app.angle1InitText.Tag = 'angle1InitText';
            app.angle1InitText.Value = '0.0';
            app.text6 = uilabel(app.GridLayout7);
            app.text6.Tag = 'text6';
            app.text6.Text = 'deg';
            app.angle1InitOptCheckbox = uicheckbox(app.GridLayout7);
            app.angle1InitOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @angle1InitOptCheckbox_Callback, true);
            app.angle1InitOptCheckbox.Tag = 'angle1InitOptCheckbox';
            app.angle1InitOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.angle1InitOptCheckbox.Text = 'Opt?';
            app.angle1InitOptCheckbox.Value = true;
            app.angle1InitLbText = uieditfield(app.GridLayout7, 'text');
            app.angle1InitLbText.ValueChangedFcn = createCallbackFcn(app, @angle1InitLbText_Callback, true);
            app.angle1InitLbText.Tag = 'angle1InitLbText';
            app.angle1InitLbText.Tooltip = 'Parameter lower bound.';
            app.angle1InitLbText.Value = '0.0';
            app.angle1InitUbText = uieditfield(app.GridLayout7, 'text');
            app.angle1InitUbText.ValueChangedFcn = createCallbackFcn(app, @angle1InitUbText_Callback, true);
            app.angle1InitUbText.Tag = 'angle1InitUbText';
            app.angle1InitUbText.Tooltip = 'Parameter upper bound.';
            app.angle1InitUbText.Value = '0.0';
            app.angle1FinalText = uieditfield(app.GridLayout7, 'text');
            app.angle1FinalText.ValueChangedFcn = createCallbackFcn(app, @angle1FinalText_Callback, true);
            app.angle1FinalText.Tag = 'angle1FinalText';
            app.angle1FinalText.Value = '0.0';
            app.text8 = uilabel(app.GridLayout7);
            app.text8.Tag = 'text8';
            app.text8.Text = 'deg';
            app.angle1FinalOptCheckbox = uicheckbox(app.GridLayout7);
            app.angle1FinalOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @angle1FinalOptCheckbox_Callback, true);
            app.angle1FinalOptCheckbox.Tag = 'angle1FinalOptCheckbox';
            app.angle1FinalOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.angle1FinalOptCheckbox.Text = 'Opt?';
            app.angle1FinalOptCheckbox.Value = true;
            app.angle1FinalLbText = uieditfield(app.GridLayout7, 'text');
            app.angle1FinalLbText.ValueChangedFcn = createCallbackFcn(app, @angle1FinalLbText_Callback, true);
            app.angle1FinalLbText.Tag = 'angle1FinalLbText';
            app.angle1FinalLbText.Tooltip = 'Parameter lower bound.';
            app.angle1FinalLbText.Value = '0.0';
            app.angle1FinalUbText = uieditfield(app.GridLayout7, 'text');
            app.angle1FinalUbText.ValueChangedFcn = createCallbackFcn(app, @angle1FinalUbText_Callback, true);
            app.angle1FinalUbText.Tag = 'angle1FinalUbText';
            app.angle1FinalUbText.Tooltip = 'Parameter upper bound.';
            app.angle1FinalUbText.Value = '0.0';
            app.angle2Panel = uipanel(app.GridLayout3);
            app.angle2Panel.Title = '<name2> Angle';
            app.angle2Panel.Tag = 'angle2Panel';
            app.GridLayout8 = uigridlayout(app.angle2Panel);
            app.text12 = uilabel(app.GridLayout8);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Initial Value';
            app.angle2InitText = uieditfield(app.GridLayout8, 'text');
            app.angle2InitText.ValueChangedFcn = createCallbackFcn(app, @angle2InitText_Callback, true);
            app.angle2InitText.Tag = 'angle2InitText';
            app.angle2InitText.Value = '0.0';
            app.text13 = uilabel(app.GridLayout8);
            app.text13.Tag = 'text13';
            app.text13.Text = 'deg';
            app.angle2InitOptCheckbox = uicheckbox(app.GridLayout8);
            app.angle2InitOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @angle2InitOptCheckbox_Callback, true);
            app.angle2InitOptCheckbox.Tag = 'angle2InitOptCheckbox';
            app.angle2InitOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.angle2InitOptCheckbox.Text = 'Opt?';
            app.angle2InitOptCheckbox.Value = true;
            app.angle2InitLbText = uieditfield(app.GridLayout8, 'text');
            app.angle2InitLbText.ValueChangedFcn = createCallbackFcn(app, @angle2InitLbText_Callback, true);
            app.angle2InitLbText.Tag = 'angle2InitLbText';
            app.angle2InitLbText.Tooltip = 'Parameter lower bound.';
            app.angle2InitLbText.Value = '0.0';
            app.angle2InitUbText = uieditfield(app.GridLayout8, 'text');
            app.angle2InitUbText.ValueChangedFcn = createCallbackFcn(app, @angle2InitUbText_Callback, true);
            app.angle2InitUbText.Tag = 'angle2InitUbText';
            app.angle2InitUbText.Tooltip = 'Parameter upper bound.';
            app.angle2InitUbText.Value = '0.0';
            app.text14 = uilabel(app.GridLayout8);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Final Value';
            app.angle2FinalText = uieditfield(app.GridLayout8, 'text');
            app.angle2FinalText.ValueChangedFcn = createCallbackFcn(app, @angle2FinalText_Callback, true);
            app.angle2FinalText.Tag = 'angle2FinalText';
            app.angle2FinalText.Value = '0.0';
            app.text15 = uilabel(app.GridLayout8);
            app.text15.Tag = 'text15';
            app.text15.Text = 'deg';
            app.angle2FinalOptCheckbox = uicheckbox(app.GridLayout8);
            app.angle2FinalOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @angle2FinalOptCheckbox_Callback, true);
            app.angle2FinalOptCheckbox.Tag = 'angle2FinalOptCheckbox';
            app.angle2FinalOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.angle2FinalOptCheckbox.Text = 'Opt?';
            app.angle2FinalOptCheckbox.Value = true;
            app.angle2FinalLbText = uieditfield(app.GridLayout8, 'text');
            app.angle2FinalLbText.ValueChangedFcn = createCallbackFcn(app, @angle2FinalLbText_Callback, true);
            app.angle2FinalLbText.Tag = 'angle2FinalLbText';
            app.angle2FinalLbText.Tooltip = 'Parameter lower bound.';
            app.angle2FinalLbText.Value = '0.0';
            app.angle2FinalUbText = uieditfield(app.GridLayout8, 'text');
            app.angle2FinalUbText.ValueChangedFcn = createCallbackFcn(app, @angle2FinalUbText_Callback, true);
            app.angle2FinalUbText.Tag = 'angle2FinalUbText';
            app.angle2FinalUbText.Tooltip = 'Parameter upper bound.';
            app.angle2FinalUbText.Value = '0.0';
            app.angle3Panel = uipanel(app.GridLayout3);
            app.angle3Panel.Title = '<name3> Angle';
            app.angle3Panel.Tag = 'angle3Panel';
            app.GridLayout9 = uigridlayout(app.angle3Panel);
            app.text18 = uilabel(app.GridLayout9);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Initial Value';
            app.angle3InitText = uieditfield(app.GridLayout9, 'text');
            app.angle3InitText.ValueChangedFcn = createCallbackFcn(app, @angle3InitText_Callback, true);
            app.angle3InitText.Tag = 'angle3InitText';
            app.angle3InitText.Value = '0.0';
            app.text19 = uilabel(app.GridLayout9);
            app.text19.Tag = 'text19';
            app.text19.Text = 'deg';
            app.angle3InitOptCheckbox = uicheckbox(app.GridLayout9);
            app.angle3InitOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @angle3InitOptCheckbox_Callback, true);
            app.angle3InitOptCheckbox.Tag = 'angle3InitOptCheckbox';
            app.angle3InitOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.angle3InitOptCheckbox.Text = 'Opt?';
            app.angle3InitOptCheckbox.Value = true;
            app.angle3InitLbText = uieditfield(app.GridLayout9, 'text');
            app.angle3InitLbText.ValueChangedFcn = createCallbackFcn(app, @angle3InitLbText_Callback, true);
            app.angle3InitLbText.Tag = 'angle3InitLbText';
            app.angle3InitLbText.Tooltip = 'Parameter lower bound.';
            app.angle3InitLbText.Value = '0.0';
            app.angle3InitUbText = uieditfield(app.GridLayout9, 'text');
            app.angle3InitUbText.ValueChangedFcn = createCallbackFcn(app, @angle3InitUbText_Callback, true);
            app.angle3InitUbText.Tag = 'angle3InitUbText';
            app.angle3InitUbText.Tooltip = 'Parameter upper bound.';
            app.angle3InitUbText.Value = '0.0';
            app.text20 = uilabel(app.GridLayout9);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Final Value';
            app.angle3FinalText = uieditfield(app.GridLayout9, 'text');
            app.angle3FinalText.ValueChangedFcn = createCallbackFcn(app, @angle3FinalText_Callback, true);
            app.angle3FinalText.Tag = 'angle3FinalText';
            app.angle3FinalText.Value = '0.0';
            app.text21 = uilabel(app.GridLayout9);
            app.text21.Tag = 'text21';
            app.text21.Text = 'deg';
            app.angle3FinalOptCheckbox = uicheckbox(app.GridLayout9);
            app.angle3FinalOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @angle3FinalOptCheckbox_Callback, true);
            app.angle3FinalOptCheckbox.Tag = 'angle3FinalOptCheckbox';
            app.angle3FinalOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.angle3FinalOptCheckbox.Text = 'Opt?';
            app.angle3FinalOptCheckbox.Value = true;
            app.angle3FinalLbText = uieditfield(app.GridLayout9, 'text');
            app.angle3FinalLbText.ValueChangedFcn = createCallbackFcn(app, @angle3FinalLbText_Callback, true);
            app.angle3FinalLbText.Tag = 'angle3FinalLbText';
            app.angle3FinalLbText.Tooltip = 'Parameter lower bound.';
            app.angle3FinalLbText.Value = '0.0';
            app.angle3FinalUbText = uieditfield(app.GridLayout9, 'text');
            app.angle3FinalUbText.ValueChangedFcn = createCallbackFcn(app, @angle3FinalUbText_Callback, true);
            app.angle3FinalUbText.Tag = 'angle3FinalUbText';
            app.angle3FinalUbText.Tooltip = 'Parameter upper bound.';
            app.angle3FinalUbText.Value = '0.0';
            % Show the figure after all components are created
            app.lvd_EditActionSetQuatInterpSteeringModelGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditActionSetQuatInterpSteeringModelGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditActionSetQuatInterpSteeringModelGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditActionSetQuatInterpSteeringModelGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditActionSetQuatInterpSteeringModelGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditActionSetQuatInterpSteeringModelGUI)
        end
    end
end