classdef lvd_EditActionSetSelectableSteeringModelGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditActionSetSelectableSteeringModelGUI  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        uipanel1                    matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        angle3Panel                 matlab.ui.container.Panel
        GridLayout9                 matlab.ui.container.GridLayout
        editAngle3ModelButton       matlab.ui.control.Button
        angle3ModelTypeCombo        matlab.ui.control.DropDown
        angle2Panel                 matlab.ui.container.Panel
        GridLayout8                 matlab.ui.container.GridLayout
        editAngle2ModelButton       matlab.ui.control.Button
        angle2ModelTypeCombo        matlab.ui.control.DropDown
        angle1Panel                 matlab.ui.container.Panel
        GridLayout7                 matlab.ui.container.GridLayout
        editAngle1ModelButton       matlab.ui.control.Button
        angle1ModelTypeCombo        matlab.ui.control.DropDown
        uipanel6                    matlab.ui.container.Panel
        GridLayout6                 matlab.ui.container.GridLayout
        timeOffsetConstUbText       matlab.ui.control.EditField
        timeOffsetConstLbText       matlab.ui.control.EditField
        timeOffsetConstOptCheckbox  matlab.ui.control.CheckBox
        text37                      matlab.ui.control.Label
        timeOffsetText              matlab.ui.control.EditField
        text36                      matlab.ui.control.Label
        angleContCheckbox           matlab.ui.control.CheckBox
        GridLayout5                 matlab.ui.container.GridLayout
        controlFrameCombo           matlab.ui.control.DropDown
        text27                      matlab.ui.control.Label
        GridLayout4                 matlab.ui.container.GridLayout
        setBaseFrameOptionsButton   matlab.ui.control.Button
        baseFrameCombo              matlab.ui.control.DropDown
        text26                      matlab.ui.control.Label
        GridLayout2                 matlab.ui.container.GridLayout
        cancelButton                matlab.ui.control.Button
        saveAndCloseButton          matlab.ui.control.Button
        titleLabel                  matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function editMathModel(app, model)
            output2 = AppDesignerGUIOutput({false});
            h = uiprogressdlg(app.lvd_EditActionSetSelectableSteeringModelGUI, "Indeterminate","on", "Message",'Opening model dialog, please wait...');
            
            switch class(model)
                case 'SumOfPolyTermsModel'
                    lvd_EditSumOfPolynomialTermsModelGUI_App(model, output2);

                case 'SumOfSinesModel'
                    lvd_EditSumOfSinesModelGUI_App(model, output2);

                case 'LinearTangentSelectableModel'
                    lvd_EditLinearTangentModelGUI_App(model, output2);

                case 'FitNetModel'
                    lvd_EditFitNetModelGUI_App(model, output2);

                otherwise
                    close(h);
                    error('Unknown angle steering model class');
            end
            
            close(h);
        end

        function bodyInfo = getSelectedBodyInfo(app, handles)
            baseFrame = getappdata(handles.lvd_EditActionSetSelectableSteeringModelGUI,'baseFrame');
            bodyInfo = baseFrame.getOriginBody();
        end

        function populateGUI(app, handles, steeringModel, lv, useContinuity)
            handles.baseFrameCombo.String = ReferenceFrameEnum.getListBoxStr();

            if(steeringModel.usesRefFrame())
                handles.baseFrameCombo.Enable = 'on';
                handles.setBaseFrameOptionsButton.Enable = 'on';
                baseFrame = steeringModel.getRefFrame();

                if(isempty(baseFrame))
                    baseFrame = lv.lvdData.getDefaultInitialBodyInfo(lv.lvdData.celBodyData).getBodyCenteredInertialFrame();
                    steeringModel.setRefFrame(baseFrame);
                end
            else
                handles.baseFrameCombo.Enable = 'off';
                handles.setBaseFrameOptionsButton.Enable = 'off';
                baseFrame = lv.lvdData.getDefaultInitialBodyInfo(lv.lvdData.celBodyData).getBodyCenteredInertialFrame();
            end
            handles.baseFrameCombo.Value = ReferenceFrameEnum.getIndForName(baseFrame.typeEnum.name);
            setappdata(handles.lvd_EditActionSetSelectableSteeringModelGUI, 'baseFrame', baseFrame);

            handles.controlFrameCombo.String = ControlFramesEnum.getListBoxStr();

            if(steeringModel.usesControlFrame())
                handles.controlFrameCombo.Enable = 'on';
                controlFrame = steeringModel.getControlFrame();

                if(isempty(controlFrame))
                    controlFrame = NedControlFrame();
                    steeringModel.setControlFrame(controlFrame);
                end

                handles.controlFrameCombo.Value = ControlFramesEnum.getIndForName(controlFrame.enum.name);

            else
                handles.controlFrameCombo.Enable = 'off';
                handles.controlFrameCombo.Value = ControlFramesEnum.getIndForName(ControlFramesEnum.NedFrame.name);
            end

            app.timeOffsetText.Value = fullAccNum2Str(steeringModel.gammaAngleModel.getTimeOffset());

            [angle1Name, angle2Name, angle3Name] = steeringModel.getAngleNames();
            set(handles.angle1Panel,'Title',sprintf('%s', angle1Name));
            set(handles.angle2Panel,'Title',sprintf('%s', angle2Name));
            set(handles.angle3Panel,'Title',sprintf('%s', angle3Name));

            steeringMathModelsStr = SteerMathModelTypeEnum.getListBoxStr();

            gammaInd = SteerMathModelTypeEnum.getIndForName(steeringModel.gammaSelModel.name);
            handles.angle1ModelTypeCombo.String = steeringMathModelsStr;
            handles.angle1ModelTypeCombo.Value = gammaInd;

            betaInd = SteerMathModelTypeEnum.getIndForName(steeringModel.betaSelModel.name);
            handles.angle2ModelTypeCombo.String = steeringMathModelsStr;
            handles.angle2ModelTypeCombo.Value = betaInd;

            alphaInd = SteerMathModelTypeEnum.getIndForName(steeringModel.alphaSelModel.name);
            handles.angle3ModelTypeCombo.String = steeringMathModelsStr;
            handles.angle3ModelTypeCombo.Value = alphaInd;

            [contTf1,contTf2,contTf3] = steeringModel.getContinuityTerms();
            contTf = any([contTf1,contTf2,contTf3]);
            if(useContinuity)
                handles.angleContCheckbox.Enable = 'on';
            else
                handles.angleContCheckbox.Enable = 'off';
            end
            handles.angleContCheckbox.Value = double(contTf);

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            angleContCheckbox_Callback(app, evt);

            optVar = steeringModel.getExistingOptVar();
            if(isempty(optVar))
                useTf = false([1,1]);
                lb = zeros(size(useTf));
                ub = zeros(size(useTf));
            else
                useTf = optVar.getUseTfForVariable();
                optVar.setUseTfForVariable(true(size(useTf)));
                [lb, ub] = optVar.getBndsForVariable();
                optVar.setUseTfForVariable(useTf);
            end

            set(handles.timeOffsetConstOptCheckbox,'Value',(useTf(1)));

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            timeOffsetConstOptCheckbox_Callback(app, evt);

            %LB
            set(handles.timeOffsetConstLbText,'String',fullAccNum2Str(lb(1)));

            %UB
            set(handles.timeOffsetConstUbText,'String',fullAccNum2Str(ub(1)));
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            %Time offset
            timeOffset = str2double(get(handles.timeOffsetText,'String'));
            enteredStr = get(handles.timeOffsetText,'String');
            numberName = 'Time Offset';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(timeOffset, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Time Offset
            timeOffsetLb = str2double(get(handles.timeOffsetConstLbText,'String'));
            enteredStr = get(handles.timeOffsetConstLbText,'String');
            numberName = 'Time Offset Lower Bound';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(timeOffsetLb, numberName, lb, ub, isInt, errMsg, enteredStr);

            timeOffsetUb = str2double(get(handles.timeOffsetConstUbText,'String'));
            enteredStr = get(handles.timeOffsetConstUbText,'String');
            numberName = 'Time Offset Upper Bound';
            lb = timeOffsetLb;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(timeOffsetUb, numberName, lb, ub, isInt, errMsg, enteredStr);
        end


        function saveAndClose(app, handles)
            lv = getappdata(handles.lvd_EditActionSetSelectableSteeringModelGUI,'lv');
            lvdData = lv.lvdData;

            steeringModel = getappdata(handles.lvd_EditActionSetSelectableSteeringModelGUI,'steeringModel');

            optVar = steeringModel.getExistingOptVar();
            if(not(isempty(optVar))) %need to remove existing var if it exists
                lvdData.optimizer.vars.removeVariable(optVar);
            end

            if(steeringModel.usesRefFrame())
                steeringModel.setRefFrame(getappdata(handles.lvd_EditActionSetSelectableSteeringModelGUI,'baseFrame'));
            end

            if(steeringModel.usesControlFrame())
                contents = cellstr(get(handles.controlFrameCombo,'String'));
                cFrameName = contents{get(handles.controlFrameCombo,'Value')};
                cFrameEnum = ControlFramesEnum.getEnumForListboxStr(cFrameName);
                cFrame = ControlFramesEnum.getControlFrameForEnum(cFrameEnum);

                steeringModel.setControlFrame(cFrame);
            end

            %Set Steering Terms
            timeOffset = str2double(get(handles.timeOffsetText,'String'));
            steeringModel.setTimeOffsets(timeOffset);

            contTf = logical(handles.angleContCheckbox.Value);
            steeringModel.setContinuityTerms(contTf,contTf,contTf);

            [~,enums] = SteerMathModelTypeEnum.getListBoxStr();
            steeringModel.gammaSelModel = enums(handles.angle1ModelTypeCombo.Value);
            steeringModel.betaSelModel = enums(handles.angle2ModelTypeCombo.Value);
            steeringModel.alphaSelModel = enums(handles.angle3ModelTypeCombo.Value);

            %Set Opt T/F
            optVar = steeringModel.getNewOptVar();
            useTf = optVar.getUseTfForVariable();
            useTf(1) = logical(handles.timeOffsetConstOptCheckbox.Value);
            optVar.setUseTfForVariable(useTf);

            %Bounds
            [lb, ub] = optVar.getAllBndsForVariable();

            lb(1) = str2double(get(handles.timeOffsetConstLbText,'String'));
            ub(1) = str2double(get(handles.timeOffsetConstUbText,'String'));

            optVar.setBndsForVariable(lb, ub);

            lvdData.optimizer.vars.addVariable(optVar);

            app.output.output = {true, steeringModel};
            close(handles.lvd_EditActionSetSelectableSteeringModelGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditActionSetSelectableSteeringModelGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditActionSetSelectableSteeringModelGUI (see VARARGIN)

            % Choose default command line output for lvd_EditActionSetSelectableSteeringModelGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            steeringModel = varargin{1};
            setappdata(hObject,'steeringModel',steeringModel);

            lv = varargin{2};
            setappdata(hObject,'lv',lv);

            useContinuity = varargin{3};
            setappdata(hObject,'useContinuity',useContinuity);

            app.output = varargin{4};

            populateGUI(app, handles, steeringModel, lv, useContinuity);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditActionSetSelectableSteeringModelGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditActionSetSelectableSteeringModelGUI);
        end

        % Value changed function: angleContCheckbox
        function angleContCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.angleContCheckbox;
            % hObject    handle to angleContCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of angleContCheckbox
            if(get(hObject,'Value'))
                %time offset
                handles.timeOffsetText.Enable = 'off';
                handles.timeOffsetConstOptCheckbox.Enable = 'off';
                handles.timeOffsetConstOptCheckbox.Value = 0;

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                timeOffsetConstOptCheckbox_Callback(app, evt);
            else
                %time offset
                handles.timeOffsetText.Enable = 'on';
                handles.timeOffsetConstOptCheckbox.Enable = 'on';

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                timeOffsetConstOptCheckbox_Callback(app, evt);
            end
        end

        % Value changed function: baseFrameCombo
        function baseFrameCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to baseFrameCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns baseFrameCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from baseFrameCombo
            lv = getappdata(handles.lvd_EditActionSetSelectableSteeringModelGUI,'lv');
            lvdData = lv.lvdData;

            contents = cellstr(get(handles.baseFrameCombo,'String'));
            selFrameType = contents{get(handles.baseFrameCombo,'Value')};
            refFrameEnum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);

            switch refFrameEnum
                case ReferenceFrameEnum.BodyCenteredInertial
                    bodyInfo = getSelectedBodyInfo(app, handles);
                    newFrame = bodyInfo.getBodyCenteredInertialFrame();

                case ReferenceFrameEnum.BodyFixedRotating
                    bodyInfo = getSelectedBodyInfo(app, handles);
                    newFrame = bodyInfo.getBodyFixedFrame();

                case ReferenceFrameEnum.TwoBodyRotating
                    bodyInfo = getSelectedBodyInfo(app, handles);
                    if(not(isempty(bodyInfo.childrenBodyInfo)))
                        primaryBody = bodyInfo;
                        secondaryBody = bodyInfo.childrenBodyInfo(1);
                    else
                        primaryBody = bodyInfo.getParBodyInfo();
                        secondaryBody = bodyInfo;
                    end

                    originPt = TwoBodyRotatingFrameOriginEnum.Primary;

                    newFrame = TwoBodyRotatingFrame(primaryBody, secondaryBody, originPt, bodyInfo.celBodyData);

                case ReferenceFrameEnum.UserDefined
                    numFrames = lvdData.geometry.refFrames.getNumRefFrames();
                    if(numFrames >= 1)
                        geometricFrame = lvdData.geometry.refFrames.getRefFrameAtInd(1);
                        newFrame = UserDefinedGeometricFrame(geometricFrame, lvdData);
                    else
                        bodyInfo = getSelectedBodyInfo(app, handles);
                        newFrame = bodyInfo.getBodyCenteredInertialFrame();
%                         warndlg('There are no geometric frames available.  A body-centered inertial frame will be selected instead.');
                        uialert(app.lvd_EditActionSetSelectableSteeringModelGUI, 'There are no geometric frames available.  A body-centered inertial frame will be selected instead.', 'No Frame', "Icon","warning");
                    end

                otherwise
                    error('Unknown reference frame type: %s', string(refFrameEnum));
            end

            if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
                refFrameEnum = newFrame.typeEnum;
                handles.baseFrameCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
            end

            setappdata(handles.lvd_EditActionSetSelectableSteeringModelGUI, 'baseFrame', newFrame);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            steeringModel = getappdata(handles.lvd_EditActionSetSelectableSteeringModelGUI,'steeringModel');
            app.output.output = {false, steeringModel};

            close(handles.lvd_EditActionSetSelectableSteeringModelGUI);
        end

        % Value changed function: controlFrameCombo
        function controlFrameCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to controlFrameCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns controlFrameCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from controlFrameCombo
            contents = cellstr(get(hObject,'String'));
            listBoxStr = contents{get(hObject,'Value')};

            enum = ControlFramesEnum.getEnumForListboxStr(listBoxStr);
            angleNames = enum.angleNames;

            set(handles.angle1Panel,'Title',sprintf('%s', angleNames{1}));
            set(handles.angle2Panel,'Title',sprintf('%s', angleNames{2}));
            set(handles.angle3Panel,'Title',sprintf('%s', angleNames{3}));
        end

        % Button pushed function: editAngle1ModelButton
        function editAngle1ModelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editAngle1ModelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            steeringModel = getappdata(handles.lvd_EditActionSetSelectableSteeringModelGUI,'steeringModel');

            [~,enums] = SteerMathModelTypeEnum.getListBoxStr();
            enum = enums(handles.angle1ModelTypeCombo.Value);

            switch enum
                case SteerMathModelTypeEnum.GenericPoly
                    model = steeringModel.gammaAngleSumPoly;

                case SteerMathModelTypeEnum.SumOfSines
                    model = steeringModel.gammaAngleSumSines;

                case SteerMathModelTypeEnum.LinearTangent
                    model = steeringModel.gammaAngleLinearTan;

                case SteerMathModelTypeEnum.FitNet
                    model = steeringModel.gammaAngleFitNet;

                otherwise
                    error('Unknown Gamma angle steering model');
            end

            app.editMathModel(model);
        end

        % Button pushed function: editAngle2ModelButton
        function editAngle2ModelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editAngle2ModelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            steeringModel = getappdata(handles.lvd_EditActionSetSelectableSteeringModelGUI,'steeringModel');

            [~,enums] = SteerMathModelTypeEnum.getListBoxStr();
            enum = enums(handles.angle2ModelTypeCombo.Value);

            switch enum
                case SteerMathModelTypeEnum.GenericPoly
                    model = steeringModel.betaAngleSumPoly;

                case SteerMathModelTypeEnum.SumOfSines
                    model = steeringModel.betaAngleSumSines;

                case SteerMathModelTypeEnum.LinearTangent
                    model = steeringModel.betaAngleLinearTan;

                case SteerMathModelTypeEnum.FitNet
                    model = steeringModel.betaAngleFitNet;

                otherwise
                    error('Unknown Beta angle steering model');
            end

            app.editMathModel(model);
        end

        % Button pushed function: editAngle3ModelButton
        function editAngle3ModelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editAngle3ModelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            steeringModel = getappdata(handles.lvd_EditActionSetSelectableSteeringModelGUI,'steeringModel');

            [~,enums] = SteerMathModelTypeEnum.getListBoxStr();
            enum = enums(handles.angle3ModelTypeCombo.Value);

            switch enum
                case SteerMathModelTypeEnum.GenericPoly
                    model = steeringModel.alphaAngleSumPoly;

                case SteerMathModelTypeEnum.SumOfSines
                    model = steeringModel.alphaAngleSumSines;

                case SteerMathModelTypeEnum.LinearTangent
                    model = steeringModel.alphaAngleLinearTan;

                case SteerMathModelTypeEnum.FitNet
                    model = steeringModel.alphaAngleFitNet;

                otherwise
                    error('Unknown Alpha angle steering model');
            end

            app.editMathModel(model);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
%                 uiresume(handles.lvd_EditActionSetSelectableSteeringModelGUI);
                app.saveAndClose(handles);
            else
%                 msgbox(errMsg,'Errors were found while editing the steering model.','error');
                uialert(app.lvd_EditActionSetSelectableSteeringModelGUI, errMsg, 'Input Errors', "Icon","error")
            end
        end

        % Button pushed function: setBaseFrameOptionsButton
        function setBaseFrameOptionsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to setBaseFrameOptionsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            baseFrame = getappdata(handles.lvd_EditActionSetSelectableSteeringModelGUI, 'baseFrame');
            newFrame = baseFrame.editFrameDialogUI(EditReferenceFrameContextEnum.ForSteering);

            setappdata(handles.lvd_EditActionSetSelectableSteeringModelGUI, 'baseFrame', newFrame);
        end

        % Value changed function: timeOffsetConstLbText
        function timeOffsetConstLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to timeOffsetConstLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of timeOffsetConstLbText as text
            %        str2double(get(hObject,'String')) returns contents of timeOffsetConstLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: timeOffsetConstOptCheckbox
        function timeOffsetConstOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.timeOffsetConstOptCheckbox;

            % hObject    handle to timeOffsetConstOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of timeOffsetConstOptCheckbox
            if(get(hObject,'Value')==1)
                set(handles.timeOffsetConstLbText,'Enable','on');
                set(handles.timeOffsetConstUbText,'Enable','on');
            else
                set(handles.timeOffsetConstLbText,'Enable','off');
                set(handles.timeOffsetConstUbText,'Enable','off');
            end
        end

        % Value changed function: timeOffsetConstUbText
        function timeOffsetConstUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to timeOffsetConstUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of timeOffsetConstUbText as text
            %        str2double(get(hObject,'String')) returns contents of timeOffsetConstUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: timeOffsetText
        function timeOffsetText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to timeOffsetText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of timeOffsetText as text
            %        str2double(get(hObject,'String')) returns contents of timeOffsetText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Window key press function: 
        % lvd_EditActionSetSelectableSteeringModelGUI
        function lvd_EditActionSetSelectableSteeringModelGUIWindowKeyPress(app, event)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(event.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditActionSetSelectableSteeringModelGUI);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditActionSetSelectableSteeringModelGUI = uifigure('Visible', 'off');
            app.lvd_EditActionSetSelectableSteeringModelGUI.Name = 'Edit Steering Model';
            app.lvd_EditActionSetSelectableSteeringModelGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditActionSetSelectableSteeringModelGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditActionSetSelectableSteeringModelGUIWindowKeyPress, true);
            app.lvd_EditActionSetSelectableSteeringModelGUI.Tag = 'lvd_EditActionSetSelectableSteeringModelGUI';
            app.GridLayout = uigridlayout(app.lvd_EditActionSetSelectableSteeringModelGUI);
            app.titleLabel = uilabel(app.GridLayout);
            app.titleLabel.Tag = 'titleLabel';
            app.titleLabel.Text = 'Edit Steering Model';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.text26 = uilabel(app.GridLayout4);
            app.text26.Tag = 'text26';
            app.text26.Text = 'Base Frame';
            app.baseFrameCombo = uidropdown(app.GridLayout4);
            app.baseFrameCombo.Items = {'Pop-up Menu'};
            app.baseFrameCombo.ValueChangedFcn = createCallbackFcn(app, @baseFrameCombo_Callback, true);
            app.baseFrameCombo.Tag = 'baseFrameCombo';
            app.baseFrameCombo.Value = 'Pop-up Menu';
            app.setBaseFrameOptionsButton = uibutton(app.GridLayout4, 'push');
            app.setBaseFrameOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @setBaseFrameOptionsButton_Callback, true);
            app.setBaseFrameOptionsButton.Tag = 'setBaseFrameOptionsButton';
            app.setBaseFrameOptionsButton.Icon = 'reference_frame.png';
            app.setBaseFrameOptionsButton.Text = 'Set Frame Options';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.text27 = uilabel(app.GridLayout5);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Control Frame';
            app.controlFrameCombo = uidropdown(app.GridLayout5);
            app.controlFrameCombo.Items = {'Pop-up Menu'};
            app.controlFrameCombo.ValueChangedFcn = createCallbackFcn(app, @controlFrameCombo_Callback, true);
            app.controlFrameCombo.Tag = 'controlFrameCombo';
            app.controlFrameCombo.Value = 'Pop-up Menu';
            app.angleContCheckbox = uicheckbox(app.GridLayout3);
            app.angleContCheckbox.ValueChangedFcn = createCallbackFcn(app, @angleContCheckbox_Callback, true);
            app.angleContCheckbox.Tag = 'angleContCheckbox';
            app.angleContCheckbox.Tooltip = 'Instead of selecting the constant terms, selecting this will carry the value of each angle into this model so that all angles are continuous in time.';
            app.angleContCheckbox.Text = 'Preserve Angle Continuity at Start of This Steering Model?';
            app.uipanel6 = uipanel(app.GridLayout3);
            app.uipanel6.Title = 'Time Offset';
            app.uipanel6.Tag = 'uipanel6';
            app.GridLayout6 = uigridlayout(app.uipanel6);
            app.text36 = uilabel(app.GridLayout6);
            app.text36.Tag = 'text36';
            app.text36.Text = 'Time Offset';
            app.timeOffsetText = uieditfield(app.GridLayout6, 'text');
            app.timeOffsetText.ValueChangedFcn = createCallbackFcn(app, @timeOffsetText_Callback, true);
            app.timeOffsetText.Tag = 'timeOffsetText';
            app.timeOffsetText.Tooltip = 'An offset to the time, t, that is used to compute the angle values.';
            app.timeOffsetText.Value = '0.0';
            app.text37 = uilabel(app.GridLayout6);
            app.text37.Tag = 'text37';
            app.text37.Text = 'sec';
            app.timeOffsetConstOptCheckbox = uicheckbox(app.GridLayout6);
            app.timeOffsetConstOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @timeOffsetConstOptCheckbox_Callback, true);
            app.timeOffsetConstOptCheckbox.Tag = 'timeOffsetConstOptCheckbox';
            app.timeOffsetConstOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.timeOffsetConstOptCheckbox.Text = 'Opt?';
            app.timeOffsetConstOptCheckbox.Value = true;
            app.timeOffsetConstLbText = uieditfield(app.GridLayout6, 'text');
            app.timeOffsetConstLbText.ValueChangedFcn = createCallbackFcn(app, @timeOffsetConstLbText_Callback, true);
            app.timeOffsetConstLbText.Tag = 'timeOffsetConstLbText';
            app.timeOffsetConstLbText.Tooltip = 'Parameter lower bound.';
            app.timeOffsetConstLbText.Value = '0.0';
            app.timeOffsetConstUbText = uieditfield(app.GridLayout6, 'text');
            app.timeOffsetConstUbText.ValueChangedFcn = createCallbackFcn(app, @timeOffsetConstUbText_Callback, true);
            app.timeOffsetConstUbText.Tag = 'timeOffsetConstUbText';
            app.timeOffsetConstUbText.Tooltip = 'Parameter upper bound.';
            app.timeOffsetConstUbText.Value = '0.0';
            app.angle1Panel = uipanel(app.GridLayout3);
            app.angle1Panel.Title = '<name1> Angle';
            app.angle1Panel.Tag = 'angle1Panel';
            app.GridLayout7 = uigridlayout(app.angle1Panel);
            app.angle1ModelTypeCombo = uidropdown(app.GridLayout7);
            app.angle1ModelTypeCombo.Items = {'Pop-up Menu'};
            app.angle1ModelTypeCombo.Tag = 'angle1ModelTypeCombo';
            app.angle1ModelTypeCombo.Value = 'Pop-up Menu';
            app.editAngle1ModelButton = uibutton(app.GridLayout7, 'push');
            app.editAngle1ModelButton.ButtonPushedFcn = createCallbackFcn(app, @editAngle1ModelButton_Callback, true);
            app.editAngle1ModelButton.Tag = 'editAngle1ModelButton';
            app.editAngle1ModelButton.Icon = 'steering-wheel.png';
            app.editAngle1ModelButton.Text = 'Edit Steering Model';
            app.angle2Panel = uipanel(app.GridLayout3);
            app.angle2Panel.Title = '<name2> Angle';
            app.angle2Panel.Tag = 'angle2Panel';
            app.GridLayout8 = uigridlayout(app.angle2Panel);
            app.angle2ModelTypeCombo = uidropdown(app.GridLayout8);
            app.angle2ModelTypeCombo.Items = {'Pop-up Menu'};
            app.angle2ModelTypeCombo.Tag = 'angle2ModelTypeCombo';
            app.angle2ModelTypeCombo.Value = 'Pop-up Menu';
            app.editAngle2ModelButton = uibutton(app.GridLayout8, 'push');
            app.editAngle2ModelButton.ButtonPushedFcn = createCallbackFcn(app, @editAngle2ModelButton_Callback, true);
            app.editAngle2ModelButton.Tag = 'editAngle2ModelButton';
            app.editAngle2ModelButton.Icon = 'steering-wheel.png';
            app.editAngle2ModelButton.Text = 'Edit Steering Model';
            app.angle3Panel = uipanel(app.GridLayout3);
            app.angle3Panel.Title = '<name3> Angle';
            app.angle3Panel.Tag = 'angle3Panel';
            app.GridLayout9 = uigridlayout(app.angle3Panel);
            app.angle3ModelTypeCombo = uidropdown(app.GridLayout9);
            app.angle3ModelTypeCombo.Items = {'Pop-up Menu'};
            app.angle3ModelTypeCombo.Tag = 'angle3ModelTypeCombo';
            app.angle3ModelTypeCombo.Value = 'Pop-up Menu';
            app.editAngle3ModelButton = uibutton(app.GridLayout9, 'push');
            app.editAngle3ModelButton.ButtonPushedFcn = createCallbackFcn(app, @editAngle3ModelButton_Callback, true);
            app.editAngle3ModelButton.Tag = 'editAngle3ModelButton';
            app.editAngle3ModelButton.Icon = 'steering-wheel.png';
            app.editAngle3ModelButton.Text = 'Edit Steering Model';
            % Show the figure after all components are created
            app.lvd_EditActionSetSelectableSteeringModelGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditActionSetSelectableSteeringModelGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditActionSetSelectableSteeringModelGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditActionSetSelectableSteeringModelGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditActionSetSelectableSteeringModelGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditActionSetSelectableSteeringModelGUI)
        end
    end
end