classdef lvd_EditActionSetSteeringModelGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditActionSetSteeringModelGUI  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        uipanel1                    matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        angle3Panel                 matlab.ui.container.Panel
        GridLayout10                matlab.ui.container.GridLayout
        GridLayout12                matlab.ui.container.GridLayout
        angle3AccelUbText           matlab.ui.control.EditField
        angle3AccelLbText           matlab.ui.control.EditField
        angle3AccelOptCheckbox      matlab.ui.control.CheckBox
        text23                      matlab.ui.control.Label
        angle3AccelTermText         matlab.ui.control.EditField
        text22                      matlab.ui.control.Label
        angle3LinUbText             matlab.ui.control.EditField
        angle3LinLbText             matlab.ui.control.EditField
        angle3LinOptCheckbox        matlab.ui.control.CheckBox
        text21                      matlab.ui.control.Label
        angle3LinTermText           matlab.ui.control.EditField
        text20                      matlab.ui.control.Label
        angle3ConstUbText           matlab.ui.control.EditField
        angle3ConstLbText           matlab.ui.control.EditField
        angle3ConstOptCheckbox      matlab.ui.control.CheckBox
        text19                      matlab.ui.control.Label
        angle3ConstTermText         matlab.ui.control.EditField
        text18                      matlab.ui.control.Label
        text30                      matlab.ui.control.Label
        angle2Panel                 matlab.ui.container.Panel
        GridLayout9                 matlab.ui.container.GridLayout
        GridLayout11                matlab.ui.container.GridLayout
        angle2AccelUbText           matlab.ui.control.EditField
        angle2LinUbText             matlab.ui.control.EditField
        angle2ConstUbText           matlab.ui.control.EditField
        angle2AccelLbText           matlab.ui.control.EditField
        angle2LinLbText             matlab.ui.control.EditField
        angle2ConstLbText           matlab.ui.control.EditField
        angle2AccelOptCheckbox      matlab.ui.control.CheckBox
        angle2LinOptCheckbox        matlab.ui.control.CheckBox
        angle2ConstOptCheckbox      matlab.ui.control.CheckBox
        text17                      matlab.ui.control.Label
        text15                      matlab.ui.control.Label
        text13                      matlab.ui.control.Label
        angle2AccelTermText         matlab.ui.control.EditField
        angle2LinTermText           matlab.ui.control.EditField
        angle2ConstTermText         matlab.ui.control.EditField
        text16                      matlab.ui.control.Label
        text14                      matlab.ui.control.Label
        text12                      matlab.ui.control.Label
        text29                      matlab.ui.control.Label
        angle1Panel                 matlab.ui.container.Panel
        GridLayout7                 matlab.ui.container.GridLayout
        GridLayout8                 matlab.ui.container.GridLayout
        angle1AccelUbText           matlab.ui.control.EditField
        angle1AccelLbText           matlab.ui.control.EditField
        angle1AccelOptCheckbox      matlab.ui.control.CheckBox
        text10                      matlab.ui.control.Label
        angle1AccelTermText         matlab.ui.control.EditField
        angle1LinUbText             matlab.ui.control.EditField
        angle1LinLbText             matlab.ui.control.EditField
        angle1LinOptCheckbox        matlab.ui.control.CheckBox
        text8                       matlab.ui.control.Label
        angle1LinTermText           matlab.ui.control.EditField
        text9                       matlab.ui.control.Label
        text7                       matlab.ui.control.Label
        angle1ConstUbText           matlab.ui.control.EditField
        angle1ConstLbText           matlab.ui.control.EditField
        angle1ConstOptCheckbox      matlab.ui.control.CheckBox
        text6                       matlab.ui.control.Label
        angle1ConstTermText         matlab.ui.control.EditField
        text5                       matlab.ui.control.Label
        text28                      matlab.ui.control.Label
        uipanel6                    matlab.ui.container.Panel
        GridLayout6                 matlab.ui.container.GridLayout
        timeOffsetConstUbText       matlab.ui.control.EditField
        timeOffsetConstLbText       matlab.ui.control.EditField
        timeOffsetConstOptCheckbox  matlab.ui.control.CheckBox
        text32                      matlab.ui.control.Label
        timeOffsetText              matlab.ui.control.EditField
        text31                      matlab.ui.control.Label
        angleContCheckbox           matlab.ui.control.CheckBox
        GridLayout5                 matlab.ui.container.GridLayout
        controlFrameCombo           matlab.ui.control.DropDown
        text27                      matlab.ui.control.Label
        GridLayout4                 matlab.ui.container.GridLayout
        setBaseFrameOptionsButton   matlab.ui.control.Button
        baseFrameCombo              matlab.ui.control.DropDown
        text26                      matlab.ui.control.Label
        steeringModelTypeCombo      matlab.ui.control.DropDown
        GridLayout2                 matlab.ui.container.GridLayout
        cancelButton                matlab.ui.control.Button
        saveAndCloseButton          matlab.ui.control.Button
        titleLabel                  matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function bodyInfo = getSelectedBodyInfo(app, handles)
            baseFrame = getappdata(handles.lvd_EditActionSetSteeringModelGUI,'baseFrame');
            bodyInfo = baseFrame.getOriginBody();
        end

        function populateGUI(app, handles, steeringModel, lv, useContinuity)
            handles.baseFrameCombo.String = ReferenceFrameEnum.getListBoxStr();
            %     handles.baseFrameCombo.Value = ReferenceFrameEnum.getIndForName(initStateModel.orbitModel.frame.typeEnum.name);
            %     handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', initStateModel.orbitModel.frame.getNameStr());

            if(steeringModel.usesRefFrame() && ~isempty(steeringModel.getRefFrame()))
                handles.baseFrameCombo.Enable = 'on';
                handles.setBaseFrameOptionsButton.Enable = 'on';
                baseFrame = steeringModel.getRefFrame();

            else
                handles.baseFrameCombo.Enable = 'off';
                handles.setBaseFrameOptionsButton.Enable = 'off';
                baseFrame = lv.lvdData.getDefaultInitialBodyInfo(lv.lvdData.celBodyData).getBodyCenteredInertialFrame();
            end
            handles.baseFrameCombo.Value = ReferenceFrameEnum.getIndForName(baseFrame.typeEnum.name);
            setappdata(handles.lvd_EditActionSetSteeringModelGUI, 'baseFrame', baseFrame);

            handles.controlFrameCombo.String = ControlFramesEnum.getListBoxStr();

            if(steeringModel.usesControlFrame())
                handles.controlFrameCombo.Enable = 'on';
                handles.controlFrameCombo.Value = ControlFramesEnum.getIndForName(steeringModel.getControlFrame().enum.name);

            else
                handles.controlFrameCombo.Enable = 'off';
                handles.controlFrameCombo.Value = ControlFramesEnum.getIndForName(ControlFramesEnum.NedFrame.name);
            end

            [angle1Name, angle2Name, angle3Name] = steeringModel.getAngleNames();
            set(handles.angle1Panel,'Title',sprintf('%s Angle', angle1Name));
            set(handles.angle2Panel,'Title',sprintf('%s Angle', angle2Name));
            set(handles.angle3Panel,'Title',sprintf('%s Angle', angle3Name));

            strs = SteeringModelEnum.getSteeringModelTypeNameStrs(true);
            set(handles.steeringModelTypeCombo,'String',strs);
            ind = SteeringModelEnum.getIndOfListboxStrsForSteeringModel(steeringModel);
            set(handles.steeringModelTypeCombo,'Value',ind);

            [angleModel, ~] = steeringModel.getAngleNModel(1);
            handles.timeOffsetText.String = fullAccNum2Str(angleModel.tOffset); %first angle model will be the offset used everywhere
            set(handles.angle1ConstTermText,'String',fullAccNum2Str(rad2deg(angleModel.constTerm)));
            set(handles.angle1LinTermText,'String',fullAccNum2Str(rad2deg(angleModel.linearTerm)));
            set(handles.angle1AccelTermText,'String',fullAccNum2Str(rad2deg(angleModel.accelTerm)));

            [angleModel, ~] = steeringModel.getAngleNModel(2);
            set(handles.angle2ConstTermText,'String',fullAccNum2Str(rad2deg(angleModel.constTerm)));
            set(handles.angle2LinTermText,'String',fullAccNum2Str(rad2deg(angleModel.linearTerm)));
            set(handles.angle2AccelTermText,'String',fullAccNum2Str(rad2deg(angleModel.accelTerm)));

            [angleModel, ~] = steeringModel.getAngleNModel(3);
            set(handles.angle3ConstTermText,'String',fullAccNum2Str(rad2deg(angleModel.constTerm)));
            set(handles.angle3LinTermText,'String',fullAccNum2Str(rad2deg(angleModel.linearTerm)));
            set(handles.angle3AccelTermText,'String',fullAccNum2Str(rad2deg(angleModel.accelTerm)));

            [contTf1,contTf2,contTf3] = steeringModel.getContinuityTerms();
            contTf = any([contTf1,contTf2,contTf3]);
            if(useContinuity)
                handles.angleContCheckbox.Enable = 'on';
            else
                handles.angleContCheckbox.Enable = 'off';
            end
            handles.angleContCheckbox.Value = double(contTf);
            
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            angleContCheckbox_Callback(app, evt);

            optVar = steeringModel.getExistingOptVar();
            if(isempty(optVar))
                useTf = false([1,10]);
                lb = zeros(size(useTf));
                ub = zeros(size(useTf));
            else
                useTf = optVar.getUseTfForVariable();
                optVar.setUseTfForVariable(true(size(useTf)));
                [lb, ub] = optVar.getBndsForVariable();
                optVar.setUseTfForVariable(useTf);
            end

            set(handles.angle1ConstOptCheckbox,'Value',(useTf(1)));
            set(handles.angle1LinOptCheckbox,'Value',(useTf(2)));
            set(handles.angle1AccelOptCheckbox,'Value',(useTf(3)));

            set(handles.angle2ConstOptCheckbox,'Value',(useTf(4)));
            set(handles.angle2LinOptCheckbox,'Value',(useTf(5)));
            set(handles.angle2AccelOptCheckbox,'Value',(useTf(6)));

            set(handles.angle3ConstOptCheckbox,'Value',(useTf(7)));
            set(handles.angle3LinOptCheckbox,'Value',(useTf(8)));
            set(handles.angle3AccelOptCheckbox,'Value',(useTf(9)));

            set(handles.timeOffsetConstOptCheckbox,'Value',(useTf(10)));

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            angle1ConstOptCheckbox_Callback(app, evt);
            angle2ConstOptCheckbox_Callback(app, evt);
            angle3ConstOptCheckbox_Callback(app, evt);

            angle1LinOptCheckbox_Callback(app, evt);
            angle2LinOptCheckbox_Callback(app, evt);
            angle3LinOptCheckbox_Callback(app, evt);

            angle1AccelOptCheckbox_Callback(app, evt);
            angle2AccelOptCheckbox_Callback(app, evt);
            angle3AccelOptCheckbox_Callback(app, evt);

            timeOffsetConstOptCheckbox_Callback(app, evt);

            %LB
            set(handles.angle1ConstLbText,'String',fullAccNum2Str(rad2deg(lb(1))));
            set(handles.angle1LinLbText,'String',fullAccNum2Str(rad2deg(lb(2))));
            set(handles.angle1AccelLbText,'String',fullAccNum2Str(rad2deg(lb(3))));

            set(handles.angle2ConstLbText,'String',fullAccNum2Str(rad2deg(lb(4))));
            set(handles.angle2LinLbText,'String',fullAccNum2Str(rad2deg(lb(5))));
            set(handles.angle2AccelLbText,'String',fullAccNum2Str(rad2deg(lb(6))));

            set(handles.angle3ConstLbText,'String',fullAccNum2Str(rad2deg(lb(7))));
            set(handles.angle3LinLbText,'String',fullAccNum2Str(rad2deg(lb(8))));
            set(handles.angle3AccelLbText,'String',fullAccNum2Str(rad2deg(lb(9))));

            set(handles.timeOffsetConstLbText,'String',fullAccNum2Str(lb(10)));

            %UB
            set(handles.angle1ConstUbText,'String',fullAccNum2Str(rad2deg(ub(1))));
            set(handles.angle1LinUbText,'String',fullAccNum2Str(rad2deg(ub(2))));
            set(handles.angle1AccelUbText,'String',fullAccNum2Str(rad2deg(ub(3))));

            set(handles.angle2ConstUbText,'String',fullAccNum2Str(rad2deg(ub(4))));
            set(handles.angle2LinUbText,'String',fullAccNum2Str(rad2deg(ub(5))));
            set(handles.angle2AccelUbText,'String',fullAccNum2Str(rad2deg(ub(6))));

            set(handles.angle3ConstUbText,'String',fullAccNum2Str(rad2deg(ub(7))));
            set(handles.angle3LinUbText,'String',fullAccNum2Str(rad2deg(ub(8))));
            set(handles.angle3AccelUbText,'String',fullAccNum2Str(rad2deg(ub(9))));

            set(handles.timeOffsetConstUbText,'String',fullAccNum2Str(ub(10)));
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            steeringModel = getappdata(handles.lvd_EditActionSetSteeringModelGUI,'steeringModel');
            [angle1Name, angle2Name, angle3Name] = steeringModel.getAngleNames();

            %Angle 1
            angle1Const = str2double(get(handles.angle1ConstTermText,'String'));
            enteredStr = get(handles.angle1ConstTermText,'String');
            numberName = sprintf('%s Angle Constant Term', angle1Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle1Const, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle1Linear = str2double(get(handles.angle1LinTermText,'String'));
            enteredStr = get(handles.angle1LinTermText,'String');
            numberName = sprintf('%s Angle Linear Term', angle1Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle1Linear, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle1Accel = str2double(get(handles.angle1AccelTermText,'String'));
            enteredStr = get(handles.angle1AccelTermText,'String');
            numberName = sprintf('%s Angle Acceleration Term', angle1Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle1Accel, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 2
            angle2Const = str2double(get(handles.angle2ConstTermText,'String'));
            enteredStr = get(handles.angle2ConstTermText,'String');
            numberName = sprintf('%s Angle Constant Term', angle2Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle2Const, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle2Linear = str2double(get(handles.angle2LinTermText,'String'));
            enteredStr = get(handles.angle2LinTermText,'String');
            numberName = sprintf('%s Angle Linear Term', angle2Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle2Linear, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle2Accel = str2double(get(handles.angle2AccelTermText,'String'));
            enteredStr = get(handles.angle2AccelTermText,'String');
            numberName = sprintf('%s Angle Acceleration Term', angle2Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle2Accel, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 3
            angle3Const = str2double(get(handles.angle3ConstTermText,'String'));
            enteredStr = get(handles.angle3ConstTermText,'String');
            numberName = sprintf('%s Angle Constant Term', angle3Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle3Const, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle3Linear = str2double(get(handles.angle3LinTermText,'String'));
            enteredStr = get(handles.angle3LinTermText,'String');
            numberName = sprintf('%s Angle Linear Term', angle3Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle3Linear, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle3Accel = str2double(get(handles.angle3AccelTermText,'String'));
            enteredStr = get(handles.angle3AccelTermText,'String');
            numberName = sprintf('%s Angle Acceleration Term', angle3Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle3Accel, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Time offset
            timeOffset = str2double(get(handles.timeOffsetText,'String'));
            enteredStr = get(handles.timeOffsetText,'String');
            numberName = 'Time Offset';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(timeOffset, numberName, lb, ub, isInt, errMsg, enteredStr);

            %%%%%Bounds
            %Angle 1 Const
            angle1ConstLB = str2double(get(handles.angle1ConstLbText,'String'));
            enteredStr = get(handles.angle1ConstLbText,'String');
            numberName = sprintf('%s Angle Constant Term Lower Bound', angle1Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle1ConstLB, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle1ConstUB = str2double(get(handles.angle1ConstUbText,'String'));
            enteredStr = get(handles.angle1ConstUbText,'String');
            numberName = sprintf('%s Angle Constant Term Upper Bound', angle1Name);
            lb = angle1ConstLB;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle1ConstUB, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 1 Linear
            angle1LinearLB = str2double(get(handles.angle1LinLbText,'String'));
            enteredStr = get(handles.angle1LinLbText,'String');
            numberName = sprintf('%s Angle Linear Term Lower Bound', angle1Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle1LinearLB, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle1LinearUB = str2double(get(handles.angle1LinUbText,'String'));
            enteredStr = get(handles.angle1LinUbText,'String');
            numberName = sprintf('%s Angle Linear Term Upper Bound', angle1Name);
            lb = angle1LinearLB;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle1LinearUB, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 1 Accel
            angle1AccelLB = str2double(get(handles.angle1AccelLbText,'String'));
            enteredStr = get(handles.angle1AccelLbText,'String');
            numberName = sprintf('%s Angle Acceleration Term Lower Bound', angle1Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle1AccelLB, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle1AccelUB = str2double(get(handles.angle1AccelUbText,'String'));
            enteredStr = get(handles.angle1AccelUbText,'String');
            numberName = sprintf('%s Angle Acceleration Term Upper Bound', angle1Name);
            lb = angle1AccelLB;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle1AccelUB, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 2 Const
            angle2ConstLB = str2double(get(handles.angle2ConstLbText,'String'));
            enteredStr = get(handles.angle2ConstLbText,'String');
            numberName = sprintf('%s Angle Constant Term Lower Bound', angle2Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle2ConstLB, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle2ConstUB = str2double(get(handles.angle2ConstUbText,'String'));
            enteredStr = get(handles.angle2ConstUbText,'String');
            numberName = sprintf('%s Angle Constant Term Upper Bound', angle2Name);
            lb = angle2ConstLB;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle2ConstUB, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 2 Linear
            angle2LinearLB = str2double(get(handles.angle2LinLbText,'String'));
            enteredStr = get(handles.angle2LinLbText,'String');
            numberName = sprintf('%s Angle Linear Term Lower Bound', angle2Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle2LinearLB, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle2LinearUB = str2double(get(handles.angle2LinUbText,'String'));
            enteredStr = get(handles.angle2LinUbText,'String');
            numberName = sprintf('%s Angle Linear Term Upper Bound', angle2Name);
            lb = angle2LinearLB;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle2LinearUB, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 2 Accel
            angle2AccelLB = str2double(get(handles.angle2AccelLbText,'String'));
            enteredStr = get(handles.angle2AccelLbText,'String');
            numberName = sprintf('%s Angle Acceleration Term Lower Bound', angle2Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle2AccelLB, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle2AccelUB = str2double(get(handles.angle2AccelUbText,'String'));
            enteredStr = get(handles.angle2AccelUbText,'String');
            numberName = sprintf('%s Angle Acceleration Term Upper Bound', angle2Name);
            lb = angle2AccelLB;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle2AccelUB, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 3 Const
            angle3ConstLB = str2double(get(handles.angle3ConstLbText,'String'));
            enteredStr = get(handles.angle3ConstLbText,'String');
            numberName = sprintf('%s Angle Constant Term Lower Bound', angle3Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle3ConstLB, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle3ConstUB = str2double(get(handles.angle3ConstUbText,'String'));
            enteredStr = get(handles.angle3ConstUbText,'String');
            numberName = sprintf('%s Angle Constant Term Upper Bound', angle3Name);
            lb = angle3ConstLB;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle3ConstUB, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 2 Linear
            angle3LinearLB = str2double(get(handles.angle3LinLbText,'String'));
            enteredStr = get(handles.angle3LinLbText,'String');
            numberName = sprintf('%s Angle Linear Term Lower Bound', angle3Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle3LinearLB, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle3LinearUB = str2double(get(handles.angle3LinUbText,'String'));
            enteredStr = get(handles.angle3LinUbText,'String');
            numberName = sprintf('%s Angle Linear Term Upper Bound', angle3Name);
            lb = angle3LinearLB;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle3LinearUB, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 2 Accel
            angle3AccelLB = str2double(get(handles.angle3AccelLbText,'String'));
            enteredStr = get(handles.angle3AccelLbText,'String');
            numberName = sprintf('%s Angle Acceleration Term Lower Bound', angle3Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle3AccelLB, numberName, lb, ub, isInt, errMsg, enteredStr);

            angle3AccelUB = str2double(get(handles.angle3AccelUbText,'String'));
            enteredStr = get(handles.angle3AccelUbText,'String');
            numberName = sprintf('%s Angle Acceleration Term Upper Bound', angle3Name);
            lb = angle3AccelLB;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle3AccelUB, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Time Offset
            timeOffsetLb = str2double(get(handles.timeOffsetConstLbText,'String'));
            enteredStr = get(handles.timeOffsetConstLbText,'String');
            numberName = 'Time Offset Lower Bound';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(timeOffsetLb, numberName, lb, ub, isInt, errMsg, enteredStr);

            timeOffsetUb = str2double(get(handles.timeOffsetConstUbText,'String'));
            enteredStr = get(handles.timeOffsetConstUbText,'String');
            numberName = 'Time Offset Upper Bound';
            lb = timeOffsetLb;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(timeOffsetUb, numberName, lb, ub, isInt, errMsg, enteredStr);
        end


        function saveAndClose(app, handles)
            lv = getappdata(handles.lvd_EditActionSetSteeringModelGUI,'lv');
            lvdData = lv.lvdData;

            steeringModel = getappdata(handles.lvd_EditActionSetSteeringModelGUI,'steeringModel');
            ind = SteeringModelEnum.getIndOfListboxStrsForSteeringModel(steeringModel);
            indFromCombo = get(handles.steeringModelTypeCombo,'Value');

            optVar = steeringModel.getExistingOptVar();
            if(not(isempty(optVar))) %need to remove existing var if it exists
                lvdData.optimizer.vars.removeVariable(optVar);
            end

            if(ind ~= indFromCombo)
                [m,~] = enumeration('SteeringModelEnum');
                steeringModel = eval(sprintf('%s.getDefaultSteeringModel()', m(indFromCombo).classNameStr));
            end

            if(steeringModel.usesRefFrame())
                steeringModel.setRefFrame(getappdata(handles.lvd_EditActionSetSteeringModelGUI,'baseFrame'));
            end

            if(steeringModel.usesControlFrame())
                contents = cellstr(get(handles.controlFrameCombo,'String'));
                cFrameName = contents{get(handles.controlFrameCombo,'Value')};
                cFrameEnum = ControlFramesEnum.getEnumForListboxStr(cFrameName);
                cFrame = ControlFramesEnum.getControlFrameForEnum(cFrameEnum);

                steeringModel.setControlFrame(cFrame);
            end

            %Set Steering Terms
            timeOffset = str2double(get(handles.timeOffsetText,'String'));

            angle1Const = deg2rad(str2double(get(handles.angle1ConstTermText,'String')));
            angle1Linear = deg2rad(str2double(get(handles.angle1LinTermText,'String')));
            angle1Accel = deg2rad(str2double(get(handles.angle1AccelTermText,'String')));

            angle2Const = deg2rad(str2double(get(handles.angle2ConstTermText,'String')));
            angle2Linear = deg2rad(str2double(get(handles.angle2LinTermText,'String')));
            angle2Accel = deg2rad(str2double(get(handles.angle2AccelTermText,'String')));

            angle3Const = deg2rad(str2double(get(handles.angle3ConstTermText,'String')));
            angle3Linear = deg2rad(str2double(get(handles.angle3LinTermText,'String')));
            angle3Accel = deg2rad(str2double(get(handles.angle3AccelTermText,'String')));

            steeringModel.setTimeOffsets(timeOffset);
            steeringModel.setConstTerms(angle1Const, angle2Const, angle3Const);
            steeringModel.setLinearTerms(angle1Linear, angle2Linear, angle3Linear);
            steeringModel.setAccelTerms(angle1Accel, angle2Accel, angle3Accel);

            contTf = logical(handles.angleContCheckbox.Value);
            steeringModel.setContinuityTerms(contTf,contTf,contTf);

            %Set Opt T/F
            useTf(1) = get(handles.angle1ConstOptCheckbox,'Value');
            useTf(2) = get(handles.angle1LinOptCheckbox,'Value');
            useTf(3) = get(handles.angle1AccelOptCheckbox,'Value');

            useTf(4) = get(handles.angle2ConstOptCheckbox,'Value');
            useTf(5) = get(handles.angle2LinOptCheckbox,'Value');
            useTf(6) = get(handles.angle2AccelOptCheckbox,'Value');

            useTf(7) = get(handles.angle3ConstOptCheckbox,'Value');
            useTf(8) = get(handles.angle3LinOptCheckbox,'Value');
            useTf(9) = get(handles.angle3AccelOptCheckbox,'Value');

            useTf(10) = handles.timeOffsetConstOptCheckbox.Value;

            optVar = steeringModel.getNewOptVar();

            optVar.setUseTfForVariable(useTf);

            %LB
            lb(1) = deg2rad(str2double(get(handles.angle1ConstLbText,'String')));
            lb(2) = deg2rad(str2double(get(handles.angle1LinLbText,'String')));
            lb(3) = deg2rad(str2double(get(handles.angle1AccelLbText,'String')));

            lb(4) = deg2rad(str2double(get(handles.angle2ConstLbText,'String')));
            lb(5) = deg2rad(str2double(get(handles.angle2LinLbText,'String')));
            lb(6) = deg2rad(str2double(get(handles.angle2AccelLbText,'String')));

            lb(7) = deg2rad(str2double(get(handles.angle3ConstLbText,'String')));
            lb(8) = deg2rad(str2double(get(handles.angle3LinLbText,'String')));
            lb(9) = deg2rad(str2double(get(handles.angle3AccelLbText,'String')));

            lb(10) = str2double(get(handles.timeOffsetConstLbText,'String'));

            %UB
            ub(1) = deg2rad(str2double(get(handles.angle1ConstUbText,'String')));
            ub(2) = deg2rad(str2double(get(handles.angle1LinUbText,'String')));
            ub(3) = deg2rad(str2double(get(handles.angle1AccelUbText,'String')));

            ub(4) = deg2rad(str2double(get(handles.angle2ConstUbText,'String')));
            ub(5) = deg2rad(str2double(get(handles.angle2LinUbText,'String')));
            ub(6) = deg2rad(str2double(get(handles.angle2AccelUbText,'String')));

            ub(7) = deg2rad(str2double(get(handles.angle3ConstUbText,'String')));
            ub(8) = deg2rad(str2double(get(handles.angle3LinUbText,'String')));
            ub(9) = deg2rad(str2double(get(handles.angle3AccelUbText,'String')));

            ub(10) = str2double(get(handles.timeOffsetConstUbText,'String'));

            optVar.setUseTfForVariable(true(size(lb))); %need this to get the full lb/set in there
            optVar.setBndsForVariable(lb, ub);
            optVar.setUseTfForVariable(useTf);

            lvdData.optimizer.vars.addVariable(optVar);

            app.output.output = {true, steeringModel};
            close(handles.lvd_EditActionSetSteeringModelGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditActionSetSteeringModelGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditActionSetSteeringModelGUI (see VARARGIN)

            % Choose default command line output for lvd_EditActionSetSteeringModelGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            steeringModel = varargin{1};
            setappdata(hObject,'steeringModel',steeringModel);

            lv = varargin{2};
            setappdata(hObject,'lv',lv);

            useContinuity = varargin{3};
            setappdata(hObject,'useContinuity',useContinuity);
            
            app.output = varargin{4};

            populateGUI(app, handles, steeringModel, lv, useContinuity);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditActionSetSteeringModelGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditActionSetSteeringModelGUI);
        end

        % Value changed function: angle1AccelLbText
        function angle1AccelLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle1AccelLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle1AccelLbText as text
            %        str2double(get(hObject,'String')) returns contents of angle1AccelLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle1AccelOptCheckbox
        function angle1AccelOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.angle1AccelOptCheckbox;
            
            % hObject    handle to angle1AccelOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of angle1AccelOptCheckbox
            if(get(hObject,'Value')==1)
                set(handles.angle1AccelLbText,'Enable','on');
                set(handles.angle1AccelUbText,'Enable','on');
            else
                set(handles.angle1AccelLbText,'Enable','off');
                set(handles.angle1AccelUbText,'Enable','off');
            end
        end

        % Value changed function: angle1AccelTermText
        function angle1AccelTermText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle1AccelTermText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle1AccelTermText as text
            %        str2double(get(hObject,'String')) returns contents of angle1AccelTermText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle1AccelUbText
        function angle1AccelUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle1AccelUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle1AccelUbText as text
            %        str2double(get(hObject,'String')) returns contents of angle1AccelUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle1ConstLbText
        function angle1ConstLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle1ConstLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle1ConstLbText as text
            %        str2double(get(hObject,'String')) returns contents of angle1ConstLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle1ConstOptCheckbox
        function angle1ConstOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.angle1ConstOptCheckbox;
            % hObject    handle to angle1ConstOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of angle1ConstOptCheckbox
            if(get(hObject,'Value')==1)
                set(handles.angle1ConstLbText,'Enable','on');
                set(handles.angle1ConstUbText,'Enable','on');
            else
                set(handles.angle1ConstLbText,'Enable','off');
                set(handles.angle1ConstUbText,'Enable','off');
            end
        end

        % Value changed function: angle1ConstTermText
        function angle1ConstTermText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle1ConstTermText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle1ConstTermText as text
            %        str2double(get(hObject,'String')) returns contents of angle1ConstTermText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle1ConstUbText
        function angle1ConstUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle1ConstUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle1ConstUbText as text
            %        str2double(get(hObject,'String')) returns contents of angle1ConstUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle1LinLbText
        function angle1LinLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle1LinLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle1LinLbText as text
            %        str2double(get(hObject,'String')) returns contents of angle1LinLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle1LinOptCheckbox
        function angle1LinOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.angle1LinOptCheckbox;
            
            % hObject    handle to angle1LinOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of angle1LinOptCheckbox
            if(get(hObject,'Value')==1)
                set(handles.angle1LinLbText,'Enable','on');
                set(handles.angle1LinUbText,'Enable','on');
            else
                set(handles.angle1LinLbText,'Enable','off');
                set(handles.angle1LinUbText,'Enable','off');
            end
        end

        % Value changed function: angle1LinTermText
        function angle1LinTermText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle1LinTermText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle1LinTermText as text
            %        str2double(get(hObject,'String')) returns contents of angle1LinTermText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle1LinUbText
        function angle1LinUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle1LinUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle1LinUbText as text
            %        str2double(get(hObject,'String')) returns contents of angle1LinUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle2AccelLbText
        function angle2AccelLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle2AccelLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle2AccelLbText as text
            %        str2double(get(hObject,'String')) returns contents of angle2AccelLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle2AccelOptCheckbox
        function angle2AccelOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.angle2AccelOptCheckbox;
            
            % hObject    handle to angle2AccelOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of angle2AccelOptCheckbox
            if(get(hObject,'Value')==1)
                set(handles.angle2AccelLbText,'Enable','on');
                set(handles.angle2AccelUbText,'Enable','on');
            else
                set(handles.angle2AccelLbText,'Enable','off');
                set(handles.angle2AccelUbText,'Enable','off');
            end
        end

        % Value changed function: angle2AccelTermText
        function angle2AccelTermText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle2AccelTermText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle2AccelTermText as text
            %        str2double(get(hObject,'String')) returns contents of angle2AccelTermText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle2AccelUbText
        function angle2AccelUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle2AccelUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle2AccelUbText as text
            %        str2double(get(hObject,'String')) returns contents of angle2AccelUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle2ConstLbText
        function angle2ConstLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle2ConstLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle2ConstLbText as text
            %        str2double(get(hObject,'String')) returns contents of angle2ConstLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle2ConstOptCheckbox
        function angle2ConstOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.angle2ConstOptCheckbox;
            % hObject    handle to angle2ConstOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of angle2ConstOptCheckbox
            if(get(hObject,'Value')==1)
                set(handles.angle2ConstLbText,'Enable','on');
                set(handles.angle2ConstUbText,'Enable','on');
            else
                set(handles.angle2ConstLbText,'Enable','off');
                set(handles.angle2ConstUbText,'Enable','off');
            end
        end

        % Value changed function: angle2ConstTermText
        function angle2ConstTermText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle2ConstTermText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle2ConstTermText as text
            %        str2double(get(hObject,'String')) returns contents of angle2ConstTermText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle2ConstUbText
        function angle2ConstUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle2ConstUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle2ConstUbText as text
            %        str2double(get(hObject,'String')) returns contents of angle2ConstUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle2LinLbText
        function angle2LinLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle2LinLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle2LinLbText as text
            %        str2double(get(hObject,'String')) returns contents of angle2LinLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle2LinOptCheckbox
        function angle2LinOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.angle2LinOptCheckbox;
            % hObject    handle to angle2LinOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of angle2LinOptCheckbox
            if(get(hObject,'Value')==1)
                set(handles.angle2LinLbText,'Enable','on');
                set(handles.angle2LinUbText,'Enable','on');
            else
                set(handles.angle2LinLbText,'Enable','off');
                set(handles.angle2LinUbText,'Enable','off');
            end
        end

        % Value changed function: angle2LinTermText
        function angle2LinTermText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle2LinTermText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle2LinTermText as text
            %        str2double(get(hObject,'String')) returns contents of angle2LinTermText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle2LinUbText
        function angle2LinUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle2LinUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle2LinUbText as text
            %        str2double(get(hObject,'String')) returns contents of angle2LinUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle3AccelLbText
        function angle3AccelLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle3AccelLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle3AccelLbText as text
            %        str2double(get(hObject,'String')) returns contents of angle3AccelLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle3AccelOptCheckbox
        function angle3AccelOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.angle3AccelOptCheckbox;
            
            % hObject    handle to angle3AccelOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of angle3AccelOptCheckbox
            if(get(hObject,'Value')==1)
                set(handles.angle3AccelLbText,'Enable','on');
                set(handles.angle3AccelUbText,'Enable','on');
            else
                set(handles.angle3AccelLbText,'Enable','off');
                set(handles.angle3AccelUbText,'Enable','off');
            end
        end

        % Value changed function: angle3AccelTermText
        function angle3AccelTermText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle3AccelTermText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle3AccelTermText as text
            %        str2double(get(hObject,'String')) returns contents of angle3AccelTermText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle3AccelUbText
        function angle3AccelUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle3AccelUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle3AccelUbText as text
            %        str2double(get(hObject,'String')) returns contents of angle3AccelUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle3ConstLbText
        function angle3ConstLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle3ConstLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle3ConstLbText as text
            %        str2double(get(hObject,'String')) returns contents of angle3ConstLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle3ConstOptCheckbox
        function angle3ConstOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.angle3ConstOptCheckbox;
            
            % hObject    handle to angle3ConstOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of angle3ConstOptCheckbox
            if(get(hObject,'Value')==1)
                set(handles.angle3ConstLbText,'Enable','on');
                set(handles.angle3ConstUbText,'Enable','on');
            else
                set(handles.angle3ConstLbText,'Enable','off');
                set(handles.angle3ConstUbText,'Enable','off');
            end
        end

        % Value changed function: angle3ConstTermText
        function angle3ConstTermText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle3ConstTermText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle3ConstTermText as text
            %        str2double(get(hObject,'String')) returns contents of angle3ConstTermText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle3ConstUbText
        function angle3ConstUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle3ConstUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle3ConstUbText as text
            %        str2double(get(hObject,'String')) returns contents of angle3ConstUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle3LinLbText
        function angle3LinLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle3LinLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle3LinLbText as text
            %        str2double(get(hObject,'String')) returns contents of angle3LinLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle3LinOptCheckbox
        function angle3LinOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.angle3LinOptCheckbox;
            
            % hObject    handle to angle3LinOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of angle3LinOptCheckbox
            if(get(hObject,'Value')==1)
                set(handles.angle3LinLbText,'Enable','on');
                set(handles.angle3LinUbText,'Enable','on');
            else
                set(handles.angle3LinLbText,'Enable','off');
                set(handles.angle3LinUbText,'Enable','off');
            end
        end

        % Value changed function: angle3LinTermText
        function angle3LinTermText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle3LinTermText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle3LinTermText as text
            %        str2double(get(hObject,'String')) returns contents of angle3LinTermText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angle3LinUbText
        function angle3LinUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to angle3LinUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of angle3LinUbText as text
            %        str2double(get(hObject,'String')) returns contents of angle3LinUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: angleContCheckbox
        function angleContCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.angleContCheckbox;
            
            % hObject    handle to angleContCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of angleContCheckbox
            if(get(hObject,'Value'))
                %angle constants
                handles.angle1ConstTermText.Enable = 'off';
                handles.angle2ConstTermText.Enable = 'off';
                handles.angle3ConstTermText.Enable = 'off';

                handles.angle1ConstOptCheckbox.Enable = 'off';
                handles.angle2ConstOptCheckbox.Enable = 'off';
                handles.angle3ConstOptCheckbox.Enable = 'off';

                handles.angle1ConstOptCheckbox.Value = 0;
                handles.angle2ConstOptCheckbox.Value = 0;
                handles.angle3ConstOptCheckbox.Value = 0;

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                angle1ConstOptCheckbox_Callback(app, evt);
                angle2ConstOptCheckbox_Callback(app, evt);
                angle3ConstOptCheckbox_Callback(app, evt);

                %time offset
                handles.timeOffsetText.Enable = 'off';
                handles.timeOffsetConstOptCheckbox.Enable = 'off';
                handles.timeOffsetConstOptCheckbox.Value = 0;

                timeOffsetConstOptCheckbox_Callback(app, evt);
            else
                %angle constants
                handles.angle1ConstTermText.Enable = 'on';
                handles.angle2ConstTermText.Enable = 'on';
                handles.angle3ConstTermText.Enable = 'on';

                handles.angle1ConstOptCheckbox.Enable = 'on';
                handles.angle2ConstOptCheckbox.Enable = 'on';
                handles.angle3ConstOptCheckbox.Enable = 'on';

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                angle1ConstOptCheckbox_Callback(app, evt);
                angle2ConstOptCheckbox_Callback(app, evt);
                angle3ConstOptCheckbox_Callback(app, evt);

                %time offset
                handles.timeOffsetText.Enable = 'on';
                handles.timeOffsetConstOptCheckbox.Enable = 'on';

                timeOffsetConstOptCheckbox_Callback(app, evt);
            end
        end

        % Value changed function: baseFrameCombo
        function baseFrameCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to baseFrameCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns baseFrameCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from baseFrameCombo
            lv = getappdata(handles.lvd_EditActionSetSteeringModelGUI,'lv');
            lvdData = lv.lvdData;

            contents = cellstr(get(handles.baseFrameCombo,'String'));
            selFrameType = contents{get(handles.baseFrameCombo,'Value')};
            refFrameEnum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);

            switch refFrameEnum
                case ReferenceFrameEnum.BodyCenteredInertial
                    bodyInfo = getSelectedBodyInfo(app, handles);
                    newFrame = bodyInfo.getBodyCenteredInertialFrame();

                case ReferenceFrameEnum.BodyFixedRotating
                    bodyInfo = getSelectedBodyInfo(app, handles);
                    newFrame = bodyInfo.getBodyFixedFrame();

                case ReferenceFrameEnum.TwoBodyRotating
                    bodyInfo = getSelectedBodyInfo(app, handles);
                    if(not(isempty(bodyInfo.childrenBodyInfo)))
                        primaryBody = bodyInfo;
                        secondaryBody = bodyInfo.childrenBodyInfo(1);
                    else
                        primaryBody = bodyInfo.getParBodyInfo();
                        secondaryBody = bodyInfo;
                    end

                    originPt = TwoBodyRotatingFrameOriginEnum.Primary;

                    newFrame = TwoBodyRotatingFrame(primaryBody, secondaryBody, originPt, bodyInfo.celBodyData);

                case ReferenceFrameEnum.UserDefined
                    numFrames = lvdData.geometry.refFrames.getNumRefFrames();
                    if(numFrames >= 1)
                        geometricFrame = lvdData.geometry.refFrames.getRefFrameAtInd(1);
                        newFrame = UserDefinedGeometricFrame(geometricFrame, lvdData);
                    else
                        bodyInfo = getSelectedBodyInfo(app, handles);
                        newFrame = bodyInfo.getBodyCenteredInertialFrame();
%                         warndlg('There are no geometric frames available.  A body-centered inertial frame will be selected instead.');
                        uialert(app.lvd_EditActionSetSteeringModelGUI, 'There are no geometric frames available.  A body-centered inertial frame will be selected instead.', 'No Frame', "Icon","warning");
                    end
                otherwise
                    error('Unknown reference frame type: %s', string(refFrameEnum));
            end

            if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
                refFrameEnum = newFrame.typeEnum;
                handles.baseFrameCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
            end

            setappdata(handles.lvd_EditActionSetSteeringModelGUI, 'baseFrame', newFrame);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            steeringModel = getappdata(handles.lvd_EditActionSetSteeringModelGUI,'steeringModel');
            app.output.output = {false, steeringModel};

            close(handles.lvd_EditActionSetSteeringModelGUI);
        end

        % Value changed function: controlFrameCombo
        function controlFrameCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to controlFrameCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns controlFrameCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from controlFrameCombo
            contents = cellstr(get(hObject,'String'));
            listBoxStr = contents{get(hObject,'Value')};

            enum = ControlFramesEnum.getEnumForListboxStr(listBoxStr);
            angleNames = enum.angleNames;

            set(handles.angle1Panel,'Title',sprintf('%s', angleNames{1}));
            set(handles.angle2Panel,'Title',sprintf('%s', angleNames{2}));
            set(handles.angle3Panel,'Title',sprintf('%s', angleNames{3}));
        end

        % Window key press function: lvd_EditActionSetSteeringModelGUI
        function lvd_EditActionSetSteeringModelGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditActionSetSteeringModelGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditActionSetSteeringModelGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                app.saveAndClose(handles);
            else
                uialert(app.lvd_EditActionSetSteeringModelGUI, errMsg, 'Input Errors', "Icon","error");
            end
        end

        % Button pushed function: setBaseFrameOptionsButton
        function setBaseFrameOptionsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to setBaseFrameOptionsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            baseFrame = getappdata(handles.lvd_EditActionSetSteeringModelGUI, 'baseFrame');
            newFrame = baseFrame.editFrameDialogUI(EditReferenceFrameContextEnum.ForSteering);

            setappdata(handles.lvd_EditActionSetSteeringModelGUI, 'baseFrame', newFrame);
        end

        % Value changed function: steeringModelTypeCombo
        function steeringModelTypeCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to steeringModelTypeCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns steeringModelTypeCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from steeringModelTypeCombo
            indFromCombo = get(handles.steeringModelTypeCombo,'Value');

            [m,~] = enumeration('SteeringModelEnum');
            steeringModel = eval(sprintf('%s.getDefaultSteeringModel()', m(indFromCombo).classNameStr));

            [angle1Name, angle2Name, angle3Name] = steeringModel.getAngleNames();
            set(handles.angle1Panel,'Title',sprintf('%s', angle1Name));
            set(handles.angle2Panel,'Title',sprintf('%s', angle2Name));
            set(handles.angle3Panel,'Title',sprintf('%s', angle3Name));

            if(steeringModel.usesRefFrame())
                handles.baseFrameCombo.Enable = 'on';
                handles.setBaseFrameOptionsButton.Enable = 'on';
            else
                handles.baseFrameCombo.Enable = 'off';
                handles.setBaseFrameOptionsButton.Enable = 'off';
            end

            if(steeringModel.usesControlFrame())
                handles.controlFrameCombo.Enable = 'on';
            else
                handles.controlFrameCombo.Enable = 'off';
            end
        end

        % Value changed function: timeOffsetConstLbText
        function timeOffsetConstLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to timeOffsetConstLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of timeOffsetConstLbText as text
            %        str2double(get(hObject,'String')) returns contents of timeOffsetConstLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: timeOffsetConstOptCheckbox
        function timeOffsetConstOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.timeOffsetConstOptCheckbox;
            
            % hObject    handle to timeOffsetConstOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of timeOffsetConstOptCheckbox
            if(get(hObject,'Value')==1)
                set(handles.timeOffsetConstLbText,'Enable','on');
                set(handles.timeOffsetConstUbText,'Enable','on');
            else
                set(handles.timeOffsetConstLbText,'Enable','off');
                set(handles.timeOffsetConstUbText,'Enable','off');
            end
        end

        % Value changed function: timeOffsetConstUbText
        function timeOffsetConstUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to timeOffsetConstUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of timeOffsetConstUbText as text
            %        str2double(get(hObject,'String')) returns contents of timeOffsetConstUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: timeOffsetText
        function timeOffsetText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to timeOffsetText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of timeOffsetText as text
            %        str2double(get(hObject,'String')) returns contents of timeOffsetText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditActionSetSteeringModelGUI = uifigure('Visible', 'off');
            app.lvd_EditActionSetSteeringModelGUI.Name = 'Edit Steering Model';
            app.lvd_EditActionSetSteeringModelGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditActionSetSteeringModelGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditActionSetSteeringModelGUI_WindowKeyPressFcn, true);
            app.lvd_EditActionSetSteeringModelGUI.Tag = 'lvd_EditActionSetSteeringModelGUI';
            app.GridLayout = uigridlayout(app.lvd_EditActionSetSteeringModelGUI);
            app.titleLabel = uilabel(app.GridLayout);
            app.titleLabel.Tag = 'titleLabel';
            app.titleLabel.Text = 'Edit Steering Model';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.steeringModelTypeCombo = uidropdown(app.GridLayout3);
            app.steeringModelTypeCombo.Items = {'Pop-up Menu'};
            app.steeringModelTypeCombo.ValueChangedFcn = createCallbackFcn(app, @steeringModelTypeCombo_Callback, true);
            app.steeringModelTypeCombo.Tag = 'steeringModelTypeCombo';
            app.steeringModelTypeCombo.Value = 'Pop-up Menu';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.text26 = uilabel(app.GridLayout4);
            app.text26.Tag = 'text26';
            app.text26.Text = 'Base Frame';
            app.baseFrameCombo = uidropdown(app.GridLayout4);
            app.baseFrameCombo.Items = {'Pop-up Menu'};
            app.baseFrameCombo.ValueChangedFcn = createCallbackFcn(app, @baseFrameCombo_Callback, true);
            app.baseFrameCombo.Tag = 'baseFrameCombo';
            app.baseFrameCombo.Value = 'Pop-up Menu';
            app.setBaseFrameOptionsButton = uibutton(app.GridLayout4, 'push');
            app.setBaseFrameOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @setBaseFrameOptionsButton_Callback, true);
            app.setBaseFrameOptionsButton.Tag = 'setBaseFrameOptionsButton';
            app.setBaseFrameOptionsButton.Icon = 'reference_frame.png';
            app.setBaseFrameOptionsButton.Text = 'Set Frame Options';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.text27 = uilabel(app.GridLayout5);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Control Frame';
            app.controlFrameCombo = uidropdown(app.GridLayout5);
            app.controlFrameCombo.Items = {'Pop-up Menu'};
            app.controlFrameCombo.ValueChangedFcn = createCallbackFcn(app, @controlFrameCombo_Callback, true);
            app.controlFrameCombo.Tag = 'controlFrameCombo';
            app.controlFrameCombo.Value = 'Pop-up Menu';
            app.angleContCheckbox = uicheckbox(app.GridLayout3);
            app.angleContCheckbox.ValueChangedFcn = createCallbackFcn(app, @angleContCheckbox_Callback, true);
            app.angleContCheckbox.Tag = 'angleContCheckbox';
            app.angleContCheckbox.Tooltip = 'Instead of selecting the constant terms, selecting this will carry the value of each angle into this model so that all angles are continuous in time.';
            app.angleContCheckbox.Text = 'Preserve Angle Continuity at Start of This Steering Model?';
            app.uipanel6 = uipanel(app.GridLayout3);
            app.uipanel6.Title = 'Time Offset';
            app.uipanel6.Tag = 'uipanel6';
            app.GridLayout6 = uigridlayout(app.uipanel6);
            app.text31 = uilabel(app.GridLayout6);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Time Offset';
            app.timeOffsetText = uieditfield(app.GridLayout6, 'text');
            app.timeOffsetText.ValueChangedFcn = createCallbackFcn(app, @timeOffsetText_Callback, true);
            app.timeOffsetText.Tag = 'timeOffsetText';
            app.timeOffsetText.Tooltip = 'An offset to the time, t, that is used to compute the angle values.';
            app.timeOffsetText.Value = '0.0';
            app.text32 = uilabel(app.GridLayout6);
            app.text32.Tag = 'text32';
            app.text32.Text = 'sec';
            app.timeOffsetConstOptCheckbox = uicheckbox(app.GridLayout6);
            app.timeOffsetConstOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @timeOffsetConstOptCheckbox_Callback, true);
            app.timeOffsetConstOptCheckbox.Tag = 'timeOffsetConstOptCheckbox';
            app.timeOffsetConstOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.timeOffsetConstOptCheckbox.Text = 'Opt?';
            app.timeOffsetConstOptCheckbox.Value = true;
            app.timeOffsetConstLbText = uieditfield(app.GridLayout6, 'text');
            app.timeOffsetConstLbText.ValueChangedFcn = createCallbackFcn(app, @timeOffsetConstLbText_Callback, true);
            app.timeOffsetConstLbText.Tag = 'timeOffsetConstLbText';
            app.timeOffsetConstLbText.Tooltip = 'Parameter lower bound.';
            app.timeOffsetConstLbText.Value = '0.0';
            app.timeOffsetConstUbText = uieditfield(app.GridLayout6, 'text');
            app.timeOffsetConstUbText.ValueChangedFcn = createCallbackFcn(app, @timeOffsetConstUbText_Callback, true);
            app.timeOffsetConstUbText.Tag = 'timeOffsetConstUbText';
            app.timeOffsetConstUbText.Tooltip = 'Parameter upper bound.';
            app.timeOffsetConstUbText.Value = '0.0';
            app.angle1Panel = uipanel(app.GridLayout3);
            app.angle1Panel.Title = '<name1> Angle';
            app.angle1Panel.Tag = 'angle1Panel';
            app.GridLayout7 = uigridlayout(app.angle1Panel);
            app.text28 = uilabel(app.GridLayout7);
            app.text28.Tag = 'text28';
            app.text28.Tooltip = 'Where t is time since the start of this steering law plus the time offset, t = t0 + tOffset.';
            app.text28.Text = 'this_angle = const + linear*t + (1/2)*accel*t^2';
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.text5 = uilabel(app.GridLayout8);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Constant Term';
            app.angle1ConstTermText = uieditfield(app.GridLayout8, 'text');
            app.angle1ConstTermText.ValueChangedFcn = createCallbackFcn(app, @angle1ConstTermText_Callback, true);
            app.angle1ConstTermText.Tag = 'angle1ConstTermText';
            app.angle1ConstTermText.Value = '0.0';
            app.text6 = uilabel(app.GridLayout8);
            app.text6.Tag = 'text6';
            app.text6.Text = 'deg';
            app.angle1ConstOptCheckbox = uicheckbox(app.GridLayout8);
            app.angle1ConstOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @angle1ConstOptCheckbox_Callback, true);
            app.angle1ConstOptCheckbox.Tag = 'angle1ConstOptCheckbox';
            app.angle1ConstOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.angle1ConstOptCheckbox.Text = 'Opt?';
            app.angle1ConstOptCheckbox.Value = true;
            app.angle1ConstLbText = uieditfield(app.GridLayout8, 'text');
            app.angle1ConstLbText.ValueChangedFcn = createCallbackFcn(app, @angle1ConstLbText_Callback, true);
            app.angle1ConstLbText.Tag = 'angle1ConstLbText';
            app.angle1ConstLbText.Tooltip = 'Parameter lower bound.';
            app.angle1ConstLbText.Value = '0.0';
            app.angle1ConstUbText = uieditfield(app.GridLayout8, 'text');
            app.angle1ConstUbText.ValueChangedFcn = createCallbackFcn(app, @angle1ConstUbText_Callback, true);
            app.angle1ConstUbText.Tag = 'angle1ConstUbText';
            app.angle1ConstUbText.Tooltip = 'Parameter upper bound.';
            app.angle1ConstUbText.Value = '0.0';
            app.text7 = uilabel(app.GridLayout8);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Linear Term';
            app.text9 = uilabel(app.GridLayout8);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Accel. Term';
            app.angle1LinTermText = uieditfield(app.GridLayout8, 'text');
            app.angle1LinTermText.ValueChangedFcn = createCallbackFcn(app, @angle1LinTermText_Callback, true);
            app.angle1LinTermText.Tag = 'angle1LinTermText';
            app.angle1LinTermText.Value = '0.0';
            app.text8 = uilabel(app.GridLayout8);
            app.text8.Tag = 'text8';
            app.text8.Text = 'deg/s';
            app.angle1LinOptCheckbox = uicheckbox(app.GridLayout8);
            app.angle1LinOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @angle1LinOptCheckbox_Callback, true);
            app.angle1LinOptCheckbox.Tag = 'angle1LinOptCheckbox';
            app.angle1LinOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.angle1LinOptCheckbox.Text = 'Opt?';
            app.angle1LinOptCheckbox.Value = true;
            app.angle1LinLbText = uieditfield(app.GridLayout8, 'text');
            app.angle1LinLbText.ValueChangedFcn = createCallbackFcn(app, @angle1LinLbText_Callback, true);
            app.angle1LinLbText.Tag = 'angle1LinLbText';
            app.angle1LinLbText.Tooltip = 'Parameter lower bound.';
            app.angle1LinLbText.Value = '0.0';
            app.angle1LinUbText = uieditfield(app.GridLayout8, 'text');
            app.angle1LinUbText.ValueChangedFcn = createCallbackFcn(app, @angle1LinUbText_Callback, true);
            app.angle1LinUbText.Tag = 'angle1LinUbText';
            app.angle1LinUbText.Tooltip = 'Parameter upper bound.';
            app.angle1LinUbText.Value = '0.0';
            app.angle1AccelTermText = uieditfield(app.GridLayout8, 'text');
            app.angle1AccelTermText.ValueChangedFcn = createCallbackFcn(app, @angle1AccelTermText_Callback, true);
            app.angle1AccelTermText.Tag = 'angle1AccelTermText';
            app.angle1AccelTermText.Value = '0.0';
            app.text10 = uilabel(app.GridLayout8);
            app.text10.Tag = 'text10';
            app.text10.Text = 'deg/s/s';
            app.angle1AccelOptCheckbox = uicheckbox(app.GridLayout8);
            app.angle1AccelOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @angle1AccelOptCheckbox_Callback, true);
            app.angle1AccelOptCheckbox.Tag = 'angle1AccelOptCheckbox';
            app.angle1AccelOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.angle1AccelOptCheckbox.Text = 'Opt?';
            app.angle1AccelOptCheckbox.Value = true;
            app.angle1AccelLbText = uieditfield(app.GridLayout8, 'text');
            app.angle1AccelLbText.ValueChangedFcn = createCallbackFcn(app, @angle1AccelLbText_Callback, true);
            app.angle1AccelLbText.Tag = 'angle1AccelLbText';
            app.angle1AccelLbText.Tooltip = 'Parameter lower bound.';
            app.angle1AccelLbText.Value = '0.0';
            app.angle1AccelUbText = uieditfield(app.GridLayout8, 'text');
            app.angle1AccelUbText.ValueChangedFcn = createCallbackFcn(app, @angle1AccelUbText_Callback, true);
            app.angle1AccelUbText.Tag = 'angle1AccelUbText';
            app.angle1AccelUbText.Tooltip = 'Parameter upper bound.';
            app.angle1AccelUbText.Value = '0.0';
            app.angle2Panel = uipanel(app.GridLayout3);
            app.angle2Panel.Title = '<name2> Angle';
            app.angle2Panel.Tag = 'angle2Panel';
            app.GridLayout9 = uigridlayout(app.angle2Panel);
            app.text29 = uilabel(app.GridLayout9);
            app.text29.Tag = 'text29';
            app.text29.Tooltip = 'Where t is time since the start of this steering law plus the time offset, t = t0 + tOffset.';
            app.text29.Text = 'this_angle = const + linear*t + (1/2)*accel*t^2';
            app.GridLayout11 = uigridlayout(app.GridLayout9);
            app.text12 = uilabel(app.GridLayout11);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Constant Term';
            app.text14 = uilabel(app.GridLayout11);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Linear Term';
            app.text16 = uilabel(app.GridLayout11);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Accel. Term';
            app.angle2ConstTermText = uieditfield(app.GridLayout11, 'text');
            app.angle2ConstTermText.ValueChangedFcn = createCallbackFcn(app, @angle2ConstTermText_Callback, true);
            app.angle2ConstTermText.Tag = 'angle2ConstTermText';
            app.angle2ConstTermText.Value = '0.0';
            app.angle2LinTermText = uieditfield(app.GridLayout11, 'text');
            app.angle2LinTermText.ValueChangedFcn = createCallbackFcn(app, @angle2LinTermText_Callback, true);
            app.angle2LinTermText.Tag = 'angle2LinTermText';
            app.angle2LinTermText.Value = '0.0';
            app.angle2AccelTermText = uieditfield(app.GridLayout11, 'text');
            app.angle2AccelTermText.ValueChangedFcn = createCallbackFcn(app, @angle2AccelTermText_Callback, true);
            app.angle2AccelTermText.Tag = 'angle2AccelTermText';
            app.angle2AccelTermText.Value = '0.0';
            app.text13 = uilabel(app.GridLayout11);
            app.text13.Tag = 'text13';
            app.text13.Text = 'deg';
            app.text15 = uilabel(app.GridLayout11);
            app.text15.Tag = 'text15';
            app.text15.Text = 'deg/s';
            app.text17 = uilabel(app.GridLayout11);
            app.text17.Tag = 'text17';
            app.text17.Text = 'deg/s/s';
            app.angle2ConstOptCheckbox = uicheckbox(app.GridLayout11);
            app.angle2ConstOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @angle2ConstOptCheckbox_Callback, true);
            app.angle2ConstOptCheckbox.Tag = 'angle2ConstOptCheckbox';
            app.angle2ConstOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.angle2ConstOptCheckbox.Text = 'Opt?';
            app.angle2ConstOptCheckbox.Value = true;
            app.angle2LinOptCheckbox = uicheckbox(app.GridLayout11);
            app.angle2LinOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @angle2LinOptCheckbox_Callback, true);
            app.angle2LinOptCheckbox.Tag = 'angle2LinOptCheckbox';
            app.angle2LinOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.angle2LinOptCheckbox.Text = 'Opt?';
            app.angle2LinOptCheckbox.Value = true;
            app.angle2AccelOptCheckbox = uicheckbox(app.GridLayout11);
            app.angle2AccelOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @angle2AccelOptCheckbox_Callback, true);
            app.angle2AccelOptCheckbox.Tag = 'angle2AccelOptCheckbox';
            app.angle2AccelOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.angle2AccelOptCheckbox.Text = 'Opt?';
            app.angle2AccelOptCheckbox.Value = true;
            app.angle2ConstLbText = uieditfield(app.GridLayout11, 'text');
            app.angle2ConstLbText.ValueChangedFcn = createCallbackFcn(app, @angle2ConstLbText_Callback, true);
            app.angle2ConstLbText.Tag = 'angle2ConstLbText';
            app.angle2ConstLbText.Tooltip = 'Parameter lower bound.';
            app.angle2ConstLbText.Value = '0.0';
            app.angle2LinLbText = uieditfield(app.GridLayout11, 'text');
            app.angle2LinLbText.ValueChangedFcn = createCallbackFcn(app, @angle2LinLbText_Callback, true);
            app.angle2LinLbText.Tag = 'angle2LinLbText';
            app.angle2LinLbText.Tooltip = 'Parameter lower bound.';
            app.angle2LinLbText.Value = '0.0';
            app.angle2AccelLbText = uieditfield(app.GridLayout11, 'text');
            app.angle2AccelLbText.ValueChangedFcn = createCallbackFcn(app, @angle2AccelLbText_Callback, true);
            app.angle2AccelLbText.Tag = 'angle2AccelLbText';
            app.angle2AccelLbText.Tooltip = 'Parameter lower bound.';
            app.angle2AccelLbText.Value = '0.0';
            app.angle2ConstUbText = uieditfield(app.GridLayout11, 'text');
            app.angle2ConstUbText.ValueChangedFcn = createCallbackFcn(app, @angle2ConstUbText_Callback, true);
            app.angle2ConstUbText.Tag = 'angle2ConstUbText';
            app.angle2ConstUbText.Tooltip = 'Parameter upper bound.';
            app.angle2ConstUbText.Value = '0.0';
            app.angle2LinUbText = uieditfield(app.GridLayout11, 'text');
            app.angle2LinUbText.ValueChangedFcn = createCallbackFcn(app, @angle2LinUbText_Callback, true);
            app.angle2LinUbText.Tag = 'angle2LinUbText';
            app.angle2LinUbText.Tooltip = 'Parameter upper bound.';
            app.angle2LinUbText.Value = '0.0';
            app.angle2AccelUbText = uieditfield(app.GridLayout11, 'text');
            app.angle2AccelUbText.ValueChangedFcn = createCallbackFcn(app, @angle2AccelUbText_Callback, true);
            app.angle2AccelUbText.Tag = 'angle2AccelUbText';
            app.angle2AccelUbText.Tooltip = 'Parameter upper bound.';
            app.angle2AccelUbText.Value = '0.0';
            app.angle3Panel = uipanel(app.GridLayout3);
            app.angle3Panel.Title = '<name3> Angle';
            app.angle3Panel.Tag = 'angle3Panel';
            app.GridLayout10 = uigridlayout(app.angle3Panel);
            app.text30 = uilabel(app.GridLayout10);
            app.text30.Tag = 'text30';
            app.text30.Tooltip = 'Where t is time since the start of this steering law plus the time offset, t = t0 + tOffset.';
            app.text30.Text = 'this_angle = const + linear*t + (1/2)*accel*t^2';
            app.GridLayout12 = uigridlayout(app.GridLayout10);
            app.text18 = uilabel(app.GridLayout12);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Constant Term';
            app.angle3ConstTermText = uieditfield(app.GridLayout12, 'text');
            app.angle3ConstTermText.ValueChangedFcn = createCallbackFcn(app, @angle3ConstTermText_Callback, true);
            app.angle3ConstTermText.Tag = 'angle3ConstTermText';
            app.angle3ConstTermText.Value = '0.0';
            app.text19 = uilabel(app.GridLayout12);
            app.text19.Tag = 'text19';
            app.text19.Text = 'deg';
            app.angle3ConstOptCheckbox = uicheckbox(app.GridLayout12);
            app.angle3ConstOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @angle3ConstOptCheckbox_Callback, true);
            app.angle3ConstOptCheckbox.Tag = 'angle3ConstOptCheckbox';
            app.angle3ConstOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.angle3ConstOptCheckbox.Text = 'Opt?';
            app.angle3ConstOptCheckbox.Value = true;
            app.angle3ConstLbText = uieditfield(app.GridLayout12, 'text');
            app.angle3ConstLbText.ValueChangedFcn = createCallbackFcn(app, @angle3ConstLbText_Callback, true);
            app.angle3ConstLbText.Tag = 'angle3ConstLbText';
            app.angle3ConstLbText.Tooltip = 'Parameter lower bound.';
            app.angle3ConstLbText.Value = '0.0';
            app.angle3ConstUbText = uieditfield(app.GridLayout12, 'text');
            app.angle3ConstUbText.ValueChangedFcn = createCallbackFcn(app, @angle3ConstUbText_Callback, true);
            app.angle3ConstUbText.Tag = 'angle3ConstUbText';
            app.angle3ConstUbText.Tooltip = 'Parameter upper bound.';
            app.angle3ConstUbText.Value = '0.0';
            app.text20 = uilabel(app.GridLayout12);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Linear Term';
            app.angle3LinTermText = uieditfield(app.GridLayout12, 'text');
            app.angle3LinTermText.ValueChangedFcn = createCallbackFcn(app, @angle3LinTermText_Callback, true);
            app.angle3LinTermText.Tag = 'angle3LinTermText';
            app.angle3LinTermText.Value = '0.0';
            app.text21 = uilabel(app.GridLayout12);
            app.text21.Tag = 'text21';
            app.text21.Text = 'deg/s';
            app.angle3LinOptCheckbox = uicheckbox(app.GridLayout12);
            app.angle3LinOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @angle3LinOptCheckbox_Callback, true);
            app.angle3LinOptCheckbox.Tag = 'angle3LinOptCheckbox';
            app.angle3LinOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.angle3LinOptCheckbox.Text = 'Opt?';
            app.angle3LinOptCheckbox.Value = true;
            app.angle3LinLbText = uieditfield(app.GridLayout12, 'text');
            app.angle3LinLbText.ValueChangedFcn = createCallbackFcn(app, @angle3LinLbText_Callback, true);
            app.angle3LinLbText.Tag = 'angle3LinLbText';
            app.angle3LinLbText.Tooltip = 'Parameter lower bound.';
            app.angle3LinLbText.Value = '0.0';
            app.angle3LinUbText = uieditfield(app.GridLayout12, 'text');
            app.angle3LinUbText.ValueChangedFcn = createCallbackFcn(app, @angle3LinUbText_Callback, true);
            app.angle3LinUbText.Tag = 'angle3LinUbText';
            app.angle3LinUbText.Tooltip = 'Parameter upper bound.';
            app.angle3LinUbText.Value = '0.0';
            app.text22 = uilabel(app.GridLayout12);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Accel. Term';
            app.angle3AccelTermText = uieditfield(app.GridLayout12, 'text');
            app.angle3AccelTermText.ValueChangedFcn = createCallbackFcn(app, @angle3AccelTermText_Callback, true);
            app.angle3AccelTermText.Tag = 'angle3AccelTermText';
            app.angle3AccelTermText.Value = '0.0';
            app.text23 = uilabel(app.GridLayout12);
            app.text23.Tag = 'text23';
            app.text23.Text = 'deg/s/s';
            app.angle3AccelOptCheckbox = uicheckbox(app.GridLayout12);
            app.angle3AccelOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @angle3AccelOptCheckbox_Callback, true);
            app.angle3AccelOptCheckbox.Tag = 'angle3AccelOptCheckbox';
            app.angle3AccelOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.angle3AccelOptCheckbox.Text = 'Opt?';
            app.angle3AccelOptCheckbox.Value = true;
            app.angle3AccelLbText = uieditfield(app.GridLayout12, 'text');
            app.angle3AccelLbText.ValueChangedFcn = createCallbackFcn(app, @angle3AccelLbText_Callback, true);
            app.angle3AccelLbText.Tag = 'angle3AccelLbText';
            app.angle3AccelLbText.Tooltip = 'Parameter lower bound.';
            app.angle3AccelLbText.Value = '0.0';
            app.angle3AccelUbText = uieditfield(app.GridLayout12, 'text');
            app.angle3AccelUbText.ValueChangedFcn = createCallbackFcn(app, @angle3AccelUbText_Callback, true);
            app.angle3AccelUbText.Tag = 'angle3AccelUbText';
            app.angle3AccelUbText.Tooltip = 'Parameter upper bound.';
            app.angle3AccelUbText.Value = '0.0';
            % Show the figure after all components are created
            app.lvd_EditActionSetSteeringModelGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditActionSetSteeringModelGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditActionSetSteeringModelGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditActionSetSteeringModelGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditActionSetSteeringModelGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditActionSetSteeringModelGUI)
        end
    end
end