classdef lvd_EditActionSetTabularQuatInterpSteeringModelGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditSteeringModelUIFigure   matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout2                 matlab.ui.container.GridLayout
        cancelButton                matlab.ui.control.Button
        saveAndCloseButton          matlab.ui.control.Button
        GridLayout3                 matlab.ui.container.GridLayout
        SubsequentAnglesPanel       matlab.ui.container.Panel
        GridLayout9                 matlab.ui.container.GridLayout
        GridLayout8                 matlab.ui.container.GridLayout
        anglesTable                 matlab.ui.control.Table
        GridLayout10                matlab.ui.container.GridLayout
        RemoveRowButton             matlab.ui.control.Button
        AddRowButton                matlab.ui.control.Button
        InitialAttitudePanel        matlab.ui.container.Panel
        GridLayout7                 matlab.ui.container.GridLayout
        angleContCheckbox           matlab.ui.control.CheckBox
        GridLayout6                 matlab.ui.container.GridLayout
        angle3InitUbText            matlab.ui.control.EditField
        angle3InitLbText            matlab.ui.control.EditField
        angle3InitOptCheckbox       matlab.ui.control.CheckBox
        text6_3                     matlab.ui.control.Label
        angle3InitText              matlab.ui.control.EditField
        initAngle3NameLabel         matlab.ui.control.Label
        angle2InitUbText            matlab.ui.control.EditField
        angle2InitLbText            matlab.ui.control.EditField
        angle2InitOptCheckbox       matlab.ui.control.CheckBox
        text6_2                     matlab.ui.control.Label
        angle2InitText              matlab.ui.control.EditField
        initAngle2NameLabel         matlab.ui.control.Label
        angle1InitUbText            matlab.ui.control.EditField
        angle1InitLbText            matlab.ui.control.EditField
        angle1InitOptCheckbox       matlab.ui.control.CheckBox
        text6                       matlab.ui.control.Label
        angle1InitText              matlab.ui.control.EditField
        initAngle1NameLabel         matlab.ui.control.Label
        ReferenceControlFramePanel  matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        GridLayout5                 matlab.ui.container.GridLayout
        controlFrameCombo           matlab.ui.control.DropDown
        text27                      matlab.ui.control.Label
        referenceFrameSelectComp    referenceFrameSelectComp
        titleLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput % Description
        lv LaunchVehicle
        useContinuity(1,1) logical
        model GenericTabularQuatInterpSteeringModel
    end
    
    methods (Access = private)
        function populateGUI(app)
            lvdData = app.lv.lvdData;

            app.referenceFrameSelectComp.initializeWithFrames(lvdData);

            refFrame = app.model.getRefFrame();
            if(isempty(refFrame))
                refFrame = lvdData.getDefaultInitialBodyInfo(lvdData.celBodyData).getBodyCenteredInertialFrame();
                app.model.setRefFrame(refFrame);
            end
            app.referenceFrameSelectComp.setSelectedFrame(refFrame);

            controlFrame = app.model.getControlFrame();
            if(isempty(controlFrame))
                controlFrame = InertialControlFrame();
                app.model.setControlFrame(controlFrame);
            end

            [listBoxStr, m] = ControlFramesEnum.getListBoxStr();
            app.controlFrameCombo.Items = listBoxStr;
            app.controlFrameCombo.ItemsData = m;
            app.controlFrameCombo.Value = controlFrame.getControlFrameEnum();

            %set initial angles stuff
            % Angle 1 is Gamma, Angle 2 is Beta, Angle 3 is Alpha
            app.angle3InitText.Value = fullAccNum2Str(app.model.alpha0);
            app.angle2InitText.Value = fullAccNum2Str(app.model.beta0);
            app.angle1InitText.Value = fullAccNum2Str(app.model.gamma0);

            %BOUNDS and OPT checkboxes
            optVar = app.model.getExistingOptVar();
            if(isempty(optVar)) %need to remove existing var if it exists
                optVar = app.model.getNewOptVar(); %then create a new var and use this one instead.
            end

            app.angle3InitOptCheckbox.Value = logical(optVar.varAlpha0);
            app.angle2InitOptCheckbox.Value = logical(optVar.varBeta0);
            app.angle1InitOptCheckbox.Value = logical(optVar.varGamma0);

            app.angle3InitLbText.Value = fullAccNum2Str(optVar.lbAlpha0);
            app.angle2InitLbText.Value = fullAccNum2Str(optVar.lbBeta0);
            app.angle1InitLbText.Value = fullAccNum2Str(optVar.lbGamma0);

            app.angle3InitUbText.Value = fullAccNum2Str(optVar.ubAlpha0);
            app.angle2InitUbText.Value = fullAccNum2Str(optVar.ubBeta0);
            app.angle1InitUbText.Value = fullAccNum2Str(optVar.ubGamma0);

            app.setInitBoundsEnables();
            app.setContEnables();

            app.setAngleNamesFromControlFrame();

            app.setTableFromSteeringModel();
            app.setDeleteRowButtonEnable();
        end

        function setAngleNamesFromControlFrame(app)
            [angle1Name, angle2Name, angle3Name] = app.model.getAngleNames();
            
            %set initial angle labels
            app.initAngle1NameLabel.Text = angle1Name;
            app.initAngle2NameLabel.Text = angle2Name;
            app.initAngle3NameLabel.Text = angle3Name;

            %set table angle names
            colNames = app.anglesTable.ColumnName;
            colNames = strrep(colNames, 'Angle 1', angle1Name);
            colNames = strrep(colNames, 'Angle 2', angle2Name);
            colNames = strrep(colNames, 'Angle 3', angle3Name);
            app.anglesTable.ColumnName = colNames;
        end

        function setTableFromSteeringModel(app)
            app.anglesTable.ColumnFormat = {'numeric', 'logical', 'numeric', 'numeric', ...
                                            'numeric', 'logical', 'numeric', 'numeric', ...
                                            'numeric', 'logical', 'numeric', 'numeric', ...
                                            'numeric', 'logical', 'numeric', 'numeric'};

            app.anglesTable.Data = app.makeTableDataFromModel();
        end

        function tableData = makeTableDataFromModel(app)
            tableData = {};

            tDurs = app.model.tDurs;
            alphaAngs = app.model.alphaAngs;
            betaAngs = app.model.betaAngs;
            gammaAngs = app.model.gammaAngs;

            optVar = app.model.getExistingOptVar();
            if(isempty(optVar)) %need to remove existing var if it exists
                optVar = app.model.getNewOptVar(); %then create a new var and use this one instead.
            end
            
            for(i=1:length(tDurs))
                tableData(i,:) = {tDurs(i),     optVar.varTDur(i),        optVar.lbTDur(i),        optVar.ubTDur(i), ...
                                  gammaAngs(i), optVar.varGammaAngles(i), optVar.lbGammaAngles(i), optVar.ubGammaAngles(i), ...
                                  betaAngs(i),  optVar.varBetaAngles(i),  optVar.lbBetaAngles(i),  optVar.ubBetaAngles(i), ...
                                  alphaAngs(i), optVar.varAlphaAngles(i), optVar.lbAlphaAngles(i), optVar.ubAlphaAngles(i)}; %#ok<AGROW>
            end
        end

        function addRowToTableData(app)
            tableData = app.anglesTable.Data;

            tableData(end+1,:) = {1, false,    0,   1, ...
                                  0, false, -360, 360, ...
                                  0, false, -090, 090, ...
                                  0, false, -360, 360};

            app.anglesTable.Data = tableData;
        end

        function removeRowFromTableData(app, rowNum)
            tableData = app.anglesTable.Data;
            tableData(rowNum,:) = [];
            app.anglesTable.Data = tableData;
        end
        
        function setInitBoundsEnables(app)
            if(app.angle1InitOptCheckbox.Value)
                app.angle1InitLbText.Enable = "on";
                app.angle1InitUbText.Enable = "on";
            else
                app.angle1InitLbText.Enable = "off";
                app.angle1InitUbText.Enable = "off";
            end

            if(app.angle2InitOptCheckbox.Value)
                app.angle2InitLbText.Enable = "on";
                app.angle2InitUbText.Enable = "on";
            else
                app.angle2InitLbText.Enable = "off";
                app.angle2InitUbText.Enable = "off";
            end

            if(app.angle3InitOptCheckbox.Value)
                app.angle3InitLbText.Enable = "on";
                app.angle3InitUbText.Enable = "on";
            else
                app.angle3InitLbText.Enable = "off";
                app.angle3InitUbText.Enable = "off";
            end
        end
        
        function setDeleteRowButtonEnable(app)
            tableData = app.anglesTable.Data;
            if(height(tableData) > 1)
                app.RemoveRowButton.Enable = "on";
            else
                app.RemoveRowButton.Enable = "off";
            end
        end

        function errMsg = validateInputs(app)
            errMsg = {};

            [angle1Name, angle2Name, angle3Name] = app.model.getAngleNames();

            %%%% Initial Attitude
            %%% Initial Attitude Values
            %Angle 1
            angle1Init = str2double(app.angle1InitText.Value);
            enteredStr = app.angle1InitText.Value;
            numberName = sprintf('%s Initial Value', angle1Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle1Init, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 2
            angle2Init = str2double(app.angle2InitText.Value);
            enteredStr = app.angle2InitText.Value;
            numberName = sprintf('%s Initial Value', angle1Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle2Init, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 3
            angle3Init = str2double(app.angle3InitText.Value);
            enteredStr = app.angle3InitText.Value;
            numberName = sprintf('%s Initial Value', angle1Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(angle3Init, numberName, lb, ub, isInt, errMsg, enteredStr);

            %%% Initial Attitude Lower Bounds
            %Angle 1
            val = str2double(app.angle1InitLbText.Value);
            enteredStr = app.angle1InitLbText.Value;
            numberName = sprintf('%s Initial Value Lower Bound', angle1Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 2
            val = str2double(app.angle2InitLbText.Value);
            enteredStr = app.angle2InitLbText.Value;
            numberName = sprintf('%s Initial Value Lower Bound', angle2Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 3
            val = str2double(app.angle3InitLbText.Value);
            enteredStr = app.angle3InitLbText.Value;
            numberName = sprintf('%s Initial Value Lower Bound', angle3Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);

            %%% Initial Attitude Upper Bounds
            %Angle 1
            val = str2double(app.angle1InitUbText.Value);
            enteredStr = app.angle1InitUbText.Value;
            numberName = sprintf('%s Initial Value Upper Bound', angle1Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 2
            val = str2double(app.angle2InitUbText.Value);
            enteredStr = app.angle2InitUbText.Value;
            numberName = sprintf('%s Initial Value Upper Bound', angle2Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Angle 3
            val = str2double(app.angle3InitUbText.Value);
            enteredStr = app.angle3InitUbText.Value;
            numberName = sprintf('%s Initial Value Upper Bound', angle3Name);
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);

            %%%% Tabular Attitudes
            tableData = app.anglesTable.Data;

            if(isempty(tableData))
                errMsg{end+1} = 'The attitude table must have at least one row.';
            end

            %%% Tabular Attitude Values
            if(not(isempty(tableData)))
                % Check Durations
                for(i=1:height(tableData))
                    valLb = tableData{i,3};
                    enteredStr = fullAccNum2Str(valLb);
                    numberName = sprintf('Attitude %u Rotation Duration Lower Bound', i);
                    lb = 1E-10;
                    ub = Inf;
                    isInt = false;
                    errMsg = validateNumber(valLb, numberName, lb, ub, isInt, errMsg, enteredStr);

                    valUb = tableData{i,4};
                    enteredStr = fullAccNum2Str(valUb);
                    numberName = sprintf('Attitude %u Rotation Duration Upper Bound', i);
                    lb = valLb;
                    ub = Inf;
                    isInt = false;
                    errMsg = validateNumber(valUb, numberName, lb, ub, isInt, errMsg, enteredStr);

                    val = tableData{i,1};
                    enteredStr = fullAccNum2Str(val);
                    numberName = sprintf('Attitude %u Rotation Duration', i);
                    lb = valLb;
                    ub = valUb;
                    isInt = false;
                    errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
                end

                %Angle 1
                for(i=1:height(tableData))
                    valLb = tableData{i,7};
                    enteredStr = fullAccNum2Str(valLb);
                    numberName = sprintf('Attitude %u %s Angle Lower Bound', i, angle1Name);
                    lb = -Inf;
                    ub = Inf;
                    isInt = false;
                    errMsg = validateNumber(valLb, numberName, lb, ub, isInt, errMsg, enteredStr);

                    valUb = tableData{i,8};
                    enteredStr = fullAccNum2Str(valUb);
                    numberName = sprintf('Attitude %u %s Angle Upper Bound', i, angle1Name);
                    lb = valLb;
                    ub = Inf;
                    isInt = false;
                    errMsg = validateNumber(valUb, numberName, lb, ub, isInt, errMsg, enteredStr);

                    val = tableData{i,5};
                    enteredStr = fullAccNum2Str(val);
                    numberName = sprintf('Attitude %u %s Angle', i, angle1Name);
                    lb = valLb;
                    ub = valUb;
                    isInt = false;
                    errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
                end

                %Angle 2
                for(i=1:height(tableData))
                    valLb = tableData{i,11};
                    enteredStr = fullAccNum2Str(valLb);
                    numberName = sprintf('Attitude %u %s Angle Lower Bound', i, angle2Name);
                    lb = -Inf;
                    ub = Inf;
                    isInt = false;
                    errMsg = validateNumber(valLb, numberName, lb, ub, isInt, errMsg, enteredStr);

                    valUb = tableData{i,12};
                    enteredStr = fullAccNum2Str(valUb);
                    numberName = sprintf('Attitude %u %s Angle Upper Bound', i, angle2Name);
                    lb = valLb;
                    ub = Inf;
                    isInt = false;
                    errMsg = validateNumber(valUb, numberName, lb, ub, isInt, errMsg, enteredStr);

                    val = tableData{i,9};
                    enteredStr = fullAccNum2Str(val);
                    numberName = sprintf('Attitude %u %s Angle', i, angle2Name);
                    lb = valLb;
                    ub = valUb;
                    isInt = false;
                    errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
                end

                %Angle 3
                for(i=1:height(tableData))
                    valLb = tableData{i,15};
                    enteredStr = fullAccNum2Str(valLb);
                    numberName = sprintf('Attitude %u %s Angle Lower Bound', i, angle3Name);
                    lb = -Inf;
                    ub = Inf;
                    isInt = false;
                    errMsg = validateNumber(valLb, numberName, lb, ub, isInt, errMsg, enteredStr);

                    valUb = tableData{i,16};
                    enteredStr = fullAccNum2Str(valUb);
                    numberName = sprintf('Attitude %u %s Angle Upper Bound', i, angle3Name);
                    lb = valLb;
                    ub = Inf;
                    isInt = false;
                    errMsg = validateNumber(valUb, numberName, lb, ub, isInt, errMsg, enteredStr);

                    val = tableData{i,13};
                    enteredStr = fullAccNum2Str(val);
                    numberName = sprintf('Attitude %u %s Angle', i, angle3Name);
                    lb = valLb;
                    ub = valUb;
                    isInt = false;
                    errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
                end
            end
        end

        function saveAndClose(app)
            lvdData = app.lv.lvdData;

            steeringModel = app.model;

            optVar = steeringModel.getExistingOptVar();
            if(not(isempty(optVar))) %need to remove existing var if it exists
                lvdData.optimizer.vars.removeVariable(optVar);
            end
            optVar = steeringModel.getNewOptVar(); %then create a new var and use this one instead

            if(steeringModel.usesRefFrame())
                steeringModel.setRefFrame(app.referenceFrameSelectComp.getSelectedFrame());
            end

            if(steeringModel.usesControlFrame())
                cFrame = ControlFramesEnum.getControlFrameForEnum(app.controlFrameCombo.Value);
                steeringModel.setControlFrame(cFrame);
            end

            contTf = logical(app.angleContCheckbox.Value);
            steeringModel.setContinuityTerms(contTf);

            %%% Initial Angles (Values) 
            steeringModel.gamma0 = str2double(app.angle1InitText.Value);
            steeringModel.beta0 = str2double(app.angle2InitText.Value);
            steeringModel.alpha0 = str2double(app.angle3InitText.Value);

            %%% Tabular Angles
            tableData = app.anglesTable.Data;

            steeringModel.tDurs     = cell2mat(tableData(:, 1));
            steeringModel.gammaAngs = cell2mat(tableData(:, 5)); 
            steeringModel.betaAngs  = cell2mat(tableData(:, 9));
            steeringModel.alphaAngs = cell2mat(tableData(:,13));

            %%% Assemble Opt Flags
            useTf = [app.angle1InitOptCheckbox.Value; app.angle2InitOptCheckbox.Value; app.angle3InitOptCheckbox.Value; ...
                     cell2mat(tableData(:,2));
                     cell2mat(tableData(:,6)); ...
                     cell2mat(tableData(:,10)); ...
                     cell2mat(tableData(:,14))];

            %%% Assemble Lower Bounds
            lb = [str2double(app.angle1InitLbText.Value); str2double(app.angle2InitLbText.Value); str2double(app.angle3InitLbText.Value); ...
                  cell2mat(tableData(:,3));
                  cell2mat(tableData(:,7)); ...
                  cell2mat(tableData(:,11)); ...
                  cell2mat(tableData(:,15))];

            %%% Assemble Upper Bounds
            ub = [str2double(app.angle1InitUbText.Value); str2double(app.angle2InitUbText.Value); str2double(app.angle3InitUbText.Value); ...
                  cell2mat(tableData(:,4));
                  cell2mat(tableData(:,8)); ...
                  cell2mat(tableData(:,12)); ...
                  cell2mat(tableData(:,16))];

            optVar.setUseTfForVariable(true(size(lb))); %need this to get the full lb/set in there
            optVar.setBndsForVariable(lb, ub);
            optVar.setUseTfForVariable(useTf);

            lvdData.optimizer.vars.addVariable(optVar);

            app.output.output = {true, steeringModel};
            close(app.EditSteeringModelUIFigure);
        end
        
        function setContEnables(app)
            if(app.angleContCheckbox.Value)
                app.angle1InitText.Enable = "off";
                app.angle1InitLbText.Enable = "off";
                app.angle1InitUbText.Enable = "off";
                app.angle1InitOptCheckbox.Enable = "off";

                app.angle2InitText.Enable = "off";
                app.angle2InitLbText.Enable = "off";
                app.angle2InitUbText.Enable = "off";
                app.angle2InitOptCheckbox.Enable = "off";

                app.angle3InitText.Enable = "off";
                app.angle3InitLbText.Enable = "off";
                app.angle3InitUbText.Enable = "off";
                app.angle3InitOptCheckbox.Enable = "off";
            else
                app.angle1InitText.Enable = "on";
                app.angle1InitLbText.Enable = "on";
                app.angle1InitUbText.Enable = "on";
                app.angle1InitOptCheckbox.Enable = "on";

                app.angle2InitText.Enable = "on";
                app.angle2InitLbText.Enable = "on";
                app.angle2InitUbText.Enable = "on";
                app.angle2InitOptCheckbox.Enable = "on";

                app.angle3InitText.Enable = "on";
                app.angle3InitLbText.Enable = "on";
                app.angle3InitUbText.Enable = "on";
                app.angle3InitOptCheckbox.Enable = "on";

                app.setInitBoundsEnables();
            end 
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, model, lv, useContinuity, output)
            app.model = model;
            app.lv = lv;
            app.useContinuity = useContinuity;
            app.output = output;

            centerUIFigure(app.EditSteeringModelUIFigure);
            applySelectedThemeToApp(app);

            app.populateGUI();

            uiwait(app.EditSteeringModelUIFigure);
        end

        % Value changed function: controlFrameCombo
        function controlFrameComboValueChanged(app, event)
            app.setAngleNamesFromControlFrame();
        end

        % Value changed function: angle1InitOptCheckbox
        function angle1InitOptCheckboxValueChanged(app, event)
            app.setInitBoundsEnables();
        end

        % Value changed function: angle2InitOptCheckbox
        function angle2InitOptCheckboxValueChanged(app, event)
            app.setInitBoundsEnables();
        end

        % Value changed function: angle3InitOptCheckbox
        function angle3InitOptCheckboxValueChanged(app, event)
            app.setInitBoundsEnables();
        end

        % Button pushed function: AddRowButton
        function AddRowButtonPushed(app, event)
            app.addRowToTableData();

            app.setDeleteRowButtonEnable();
        end

        % Button pushed function: RemoveRowButton
        function RemoveRowButtonPushed(app, event)
            app.removeRowFromTableData(app.anglesTable.Selection);

            app.setDeleteRowButtonEnable();
        end

        % Value changed function: angleContCheckbox
        function angleContCheckboxValueChanged(app, event)
            app.setContEnables();
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButtonPushed(app, event)
            errMsg = app.validateInputs();

            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.EditSteeringModelUIFigure, errMsg, 'Input Errors', "Icon","error");
            end
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            steeringModel = app.model;
            app.output.output = {false, steeringModel};

            delete(app.EditSteeringModelUIFigure);
        end

        % Close request function: EditSteeringModelUIFigure
        function EditSteeringModelUIFigureCloseRequest(app, event)
            steeringModel = app.model;
            app.output.output = {false, steeringModel};

            delete(app.EditSteeringModelUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditSteeringModelUIFigure = uifigure('Visible', 'off');
            app.EditSteeringModelUIFigure.Name = 'Edit Steering Model';
            app.EditSteeringModelUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.EditSteeringModelUIFigure.CloseRequestFcn = createCallbackFcn(app, @EditSteeringModelUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.EditSteeringModelUIFigure);
            app.titleLabel = uilabel(app.GridLayout);
            app.titleLabel.Tag = 'titleLabel';
            app.titleLabel.Text = 'Edit Steering Model';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ReferenceControlFramePanel = uipanel(app.GridLayout3);
            app.ReferenceControlFramePanel.Title = 'Reference & Control Frame';
            app.GridLayout4 = uigridlayout(app.ReferenceControlFramePanel);
            app.referenceFrameSelectComp = referenceFrameSelectComp(app.GridLayout4);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.text27 = uilabel(app.GridLayout5);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Control Frame';
            app.controlFrameCombo = uidropdown(app.GridLayout5);
            app.controlFrameCombo.Items = {'Pop-up Menu'};
            app.controlFrameCombo.ValueChangedFcn = createCallbackFcn(app, @controlFrameComboValueChanged, true);
            app.controlFrameCombo.Tag = 'controlFrameCombo';
            app.controlFrameCombo.Value = 'Pop-up Menu';
            app.InitialAttitudePanel = uipanel(app.GridLayout3);
            app.InitialAttitudePanel.Title = 'Initial Attitude';
            app.GridLayout7 = uigridlayout(app.InitialAttitudePanel);
            app.GridLayout6 = uigridlayout(app.GridLayout7);
            app.initAngle1NameLabel = uilabel(app.GridLayout6);
            app.initAngle1NameLabel.Tag = 'text5';
            app.initAngle1NameLabel.Text = '<Name1>';
            app.angle1InitText = uieditfield(app.GridLayout6, 'text');
            app.angle1InitText.Tag = 'angle1InitText';
            app.angle1InitText.Value = '0.0';
            app.text6 = uilabel(app.GridLayout6);
            app.text6.Tag = 'text6';
            app.text6.Text = 'deg';
            app.angle1InitOptCheckbox = uicheckbox(app.GridLayout6);
            app.angle1InitOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @angle1InitOptCheckboxValueChanged, true);
            app.angle1InitOptCheckbox.Tag = 'angle1InitOptCheckbox';
            app.angle1InitOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.angle1InitOptCheckbox.Text = 'Opt?';
            app.angle1InitLbText = uieditfield(app.GridLayout6, 'text');
            app.angle1InitLbText.Tag = 'angle1InitLbText';
            app.angle1InitLbText.Tooltip = 'Parameter lower bound.';
            app.angle1InitLbText.Value = '0.0';
            app.angle1InitUbText = uieditfield(app.GridLayout6, 'text');
            app.angle1InitUbText.Tag = 'angle1InitUbText';
            app.angle1InitUbText.Tooltip = 'Parameter upper bound.';
            app.angle1InitUbText.Value = '0.0';
            app.initAngle2NameLabel = uilabel(app.GridLayout6);
            app.initAngle2NameLabel.Tag = 'text5';
            app.initAngle2NameLabel.Text = '<Name2>';
            app.angle2InitText = uieditfield(app.GridLayout6, 'text');
            app.angle2InitText.Tag = 'angle1InitText';
            app.angle2InitText.Value = '0.0';
            app.text6_2 = uilabel(app.GridLayout6);
            app.text6_2.Tag = 'text6';
            app.text6_2.Text = 'deg';
            app.angle2InitOptCheckbox = uicheckbox(app.GridLayout6);
            app.angle2InitOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @angle2InitOptCheckboxValueChanged, true);
            app.angle2InitOptCheckbox.Tag = 'angle1InitOptCheckbox';
            app.angle2InitOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.angle2InitOptCheckbox.Text = 'Opt?';
            app.angle2InitLbText = uieditfield(app.GridLayout6, 'text');
            app.angle2InitLbText.Tag = 'angle1InitLbText';
            app.angle2InitLbText.Tooltip = 'Parameter lower bound.';
            app.angle2InitLbText.Value = '0.0';
            app.angle2InitUbText = uieditfield(app.GridLayout6, 'text');
            app.angle2InitUbText.Tag = 'angle1InitUbText';
            app.angle2InitUbText.Tooltip = 'Parameter upper bound.';
            app.angle2InitUbText.Value = '0.0';
            app.initAngle3NameLabel = uilabel(app.GridLayout6);
            app.initAngle3NameLabel.Tag = 'text5';
            app.initAngle3NameLabel.Text = '<Name3>';
            app.angle3InitText = uieditfield(app.GridLayout6, 'text');
            app.angle3InitText.Tag = 'angle1InitText';
            app.angle3InitText.Value = '0.0';
            app.text6_3 = uilabel(app.GridLayout6);
            app.text6_3.Tag = 'text6';
            app.text6_3.Text = 'deg';
            app.angle3InitOptCheckbox = uicheckbox(app.GridLayout6);
            app.angle3InitOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @angle3InitOptCheckboxValueChanged, true);
            app.angle3InitOptCheckbox.Tag = 'angle1InitOptCheckbox';
            app.angle3InitOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.angle3InitOptCheckbox.Text = 'Opt?';
            app.angle3InitOptCheckbox.Value = true;
            app.angle3InitLbText = uieditfield(app.GridLayout6, 'text');
            app.angle3InitLbText.Tag = 'angle1InitLbText';
            app.angle3InitLbText.Tooltip = 'Parameter lower bound.';
            app.angle3InitLbText.Value = '0.0';
            app.angle3InitUbText = uieditfield(app.GridLayout6, 'text');
            app.angle3InitUbText.Tag = 'angle1InitUbText';
            app.angle3InitUbText.Tooltip = 'Parameter upper bound.';
            app.angle3InitUbText.Value = '0.0';
            app.angleContCheckbox = uicheckbox(app.GridLayout7);
            app.angleContCheckbox.ValueChangedFcn = createCallbackFcn(app, @angleContCheckboxValueChanged, true);
            app.angleContCheckbox.Tag = 'angleContCheckbox';
            app.angleContCheckbox.Tooltip = 'Instead of selecting the initial values, selecting this will carry the value of each angle into this model so that all angles are continuous in time.';
            app.angleContCheckbox.Text = 'Preserve Angle Continuity at Start of This Steering Model?';
            app.SubsequentAnglesPanel = uipanel(app.GridLayout3);
            app.SubsequentAnglesPanel.Title = 'Subsequent Angles';
            app.GridLayout9 = uigridlayout(app.SubsequentAnglesPanel);
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.AddRowButton = uibutton(app.GridLayout10, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowButtonPushed, true);
            app.AddRowButton.Icon = 'add.png';
            app.AddRowButton.Tooltip = {'Add new row to table.'};
            app.AddRowButton.Text = '';
            app.RemoveRowButton = uibutton(app.GridLayout10, 'push');
            app.RemoveRowButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRowButtonPushed, true);
            app.RemoveRowButton.Icon = 'remove.png';
            app.RemoveRowButton.Tooltip = {'Remove selected row from table.  There must be at least one row in the table.'};
            app.RemoveRowButton.Text = '';
            app.GridLayout8 = uigridlayout(app.GridLayout9);
            app.anglesTable = uitable(app.GridLayout8);
            app.anglesTable.ColumnName = {'Duration [s]'; 'Duration Opt?'; 'Duration LB [s]'; 'Duration UB [s]'; 'Angle 1 [deg]'; 'Angle 1 Opt?'; 'Angle 1 LB [deg]'; 'Angle 1 UB [deg]'; 'Angle 2 [deg]'; 'Angle 2 Opt?'; 'Angle 2 LB [deg]'; 'Angle 2 UB [deg]'; 'Angle 3 [deg]'; 'Angle 3 Opt?'; 'Angle 3 LB [deg]'; 'Angle 3 UB [deg]'};
            app.anglesTable.RowName = {};
            app.anglesTable.SelectionType = 'row';
            app.anglesTable.ColumnEditable = true;
            app.anglesTable.Multiselect = 'off';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButtonPushed, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.EditSteeringModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditActionSetTabularQuatInterpSteeringModelGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditSteeringModelUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditSteeringModelUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditSteeringModelUIFigure)
        end
    end
end