classdef lvd_EditFitNetModelGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditFitNetModelGUI       matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        uipanel1                     matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        uipanel3                     matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        GridLayout5                  matlab.ui.container.GridLayout
        uipanel4                     matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        wbParamUbText                matlab.ui.control.EditField
        wbParamLbText                matlab.ui.control.EditField
        wbParamOptCheckbox           matlab.ui.control.CheckBox
        text6                        matlab.ui.control.Label
        wbParamText                  matlab.ui.control.EditField
        text5                        matlab.ui.control.Label
        GridLayout6                  matlab.ui.container.GridLayout
        durationToPlot               matlab.ui.control.EditField
        text14                       matlab.ui.control.Label
        text13                       matlab.ui.control.Label
        plotPolynomialButton         matlab.ui.control.Button
        wbParamListbox               matlab.ui.control.ListBox
        text11                       matlab.ui.control.Label
        uipanel2                     matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        constUbText                  matlab.ui.control.EditField
        constLbText                  matlab.ui.control.EditField
        constOptCheckbox             matlab.ui.control.CheckBox
        text4                        matlab.ui.control.Label
        constText                    matlab.ui.control.EditField
        text3                        matlab.ui.control.Label
        saveAndCloseButton           matlab.ui.control.Button
        text2                        matlab.ui.control.Label
        listboxContextMenu           matlab.ui.container.ContextMenu
        optimizeAllParametersMenu    matlab.ui.container.Menu
        unoptimizeAllParametersMenu  matlab.ui.container.Menu
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput
    end
    
    methods (Access = private)
        function populateGUI(app, handles, fitNetModel)
                handles.constText.String = fullAccNum2Str(rad2deg(fitNetModel.const));
                handles.constOptCheckbox.Value = double(fitNetModel.varConst);
                handles.constLbText.String = fullAccNum2Str(rad2deg(fitNetModel.constLb));
                handles.constUbText.String = fullAccNum2Str(rad2deg(fitNetModel.constUb));
            
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                constOptCheckbox_Callback(app, evt);
            
                fitNetListboxStr = fitNetModel.getListboxStr();
                handles.wbParamListbox.String = fitNetListboxStr;
                if(~isempty(fitNetListboxStr))
                    handles.wbParamListbox.Value = 1;
                end
            
                populateWbParameterDefinition(app, handles, fitNetModel);
        end
        
        function populateWbParameterDefinition(app, handles, fitNetModel)
                selInd = handles.wbParamListbox.Value;
                wbParams = fitNetModel.getWbParams();
                wbParam = wbParams(selInd);
            
                varWb = fitNetModel.varNetWB(selInd);
                ub = fitNetModel.netWbUb(selInd);
                lb = fitNetModel.netWbLb(selInd);
            
                handles.wbParamText.String = fullAccNum2Str(wbParam);
                handles.wbParamOptCheckbox.Value = double(varWb);
                handles.wbParamLbText.String = fullAccNum2Str(lb);
                handles.wbParamUbText.String = fullAccNum2Str(ub);
                
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                wbParamOptCheckbox_Callback(app, evt);
        end
        
        
        function saveAndClose(app, handles)
            app.output.output = {true};
            close(handles.lvd_EditFitNetModelGUI);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditFitNetModelGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
                % This function has no output args, see OutputFcn.
                % hObject    handle to figure
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
                % varargin   command line arguments to lvd_EditFitNetModelGUI (see VARARGIN)
            
                % Choose default command line output for lvd_EditFitNetModelGUI
                handles.output = hObject;
            
                centerUIFigure(hObject);
                applySelectedThemeToApp(app);
            
                fitNetModel = varargin{1};
                setappdata(hObject,'fitNetModel',fitNetModel);
                
                app.output = varargin{2};
            
                setappdata(hObject,'plotDur',1.0);
            
                populateGUI(app, handles, fitNetModel);
            
                % Update handles structure
                guidata(hObject, handles);
            
                % UIWAIT makes lvd_EditFitNetModelGUI wait for user response (see UIRESUME)
                uiwait(handles.lvd_EditFitNetModelGUI);
        end

        % Value changed function: constLbText
        function constLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % hObject    handle to constLbText (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
            
                % Hints: get(hObject,'String') returns contents of constLbText as text
                %        str2double(get(hObject,'String')) returns contents of constLbText as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
            
                fitNetModel = getappdata(handles.lvd_EditFitNetModelGUI,'fitNetModel');
            
                errMsg = {};
            
                value = str2double(get(hObject,'String'));
                enteredStr = get(hObject,'String');
                numberName = 'Sine Constant Offset Lower Bound';
                lb = -Inf;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                if(isempty(errMsg))
                    fitNetModel.constLb = deg2rad(value);
                else
                    hObject.String = fullAccNum2Str(fitNetModel.constLb);
            
                    msgbox(errMsg,'Invalid Sine Constant Offset Lower Bound','error');
                end
        end

        % Value changed function: constOptCheckbox
        function constOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.constOptCheckbox;
                % hObject    handle to constOptCheckbox (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
            
                % Hint: get(hObject,'Value') returns toggle state of constOptCheckbox
                fitNetModel = getappdata(handles.lvd_EditFitNetModelGUI,'fitNetModel');
                fitNetModel.varConst = logical(hObject.Value);
            
                if(hObject.Value)
                    handles.constLbText.Enable = 'on';
                    handles.constUbText.Enable = 'on';
                else
                    handles.constLbText.Enable = 'off';
                    handles.constUbText.Enable = 'off';
                end
        end

        % Value changed function: constText
        function constText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % hObject    handle to constText (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
            
                % Hints: get(hObject,'String') returns contents of constText as text
                %        str2double(get(hObject,'String')) returns contents of constText as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
            
                fitNetModel = getappdata(handles.lvd_EditFitNetModelGUI,'fitNetModel');
            
                errMsg = {};
            
                value = str2double(get(hObject,'String'));
                enteredStr = get(hObject,'String');
                numberName = 'Constant Offset';
                lb = -Inf;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                if(isempty(errMsg))
                    fitNetModel.const = deg2rad(value);
                else
                    hObject.String = fullAccNum2Str(rad2deg(fitNetModel.const));
            
                    msgbox(errMsg,'Invalid Constant Offset','error');
                end
        end

        % Value changed function: constUbText
        function constUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % hObject    handle to constUbText (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
            
                % Hints: get(hObject,'String') returns contents of constUbText as text
                %        str2double(get(hObject,'String')) returns contents of constUbText as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
            
                fitNetModel = getappdata(handles.lvd_EditFitNetModelGUI,'fitNetModel');
            
                errMsg = {};
            
                value = str2double(get(hObject,'String'));
                enteredStr = get(hObject,'String');
                numberName = 'Sine Constant Offset Upper Bound';
                lb = fitNetModel.constLb;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                if(isempty(errMsg))
                    fitNetModel.constUb = deg2rad(value);
                else
                    hObject.String = fullAccNum2Str(fitNetModel.constUb);
            
                    msgbox(errMsg,'Invalid Sine Constant Offset Upper Bound','error');
                end
        end

        % Value changed function: durationToPlot
        function durationToPlot_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to durationToPlot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of durationToPlot as text
            %        str2double(get(hObject,'String')) returns contents of durationToPlot as a double
                errMsg = {};
            
                value = str2double(get(hObject,'String'));
                enteredStr = get(hObject,'String');
                numberName = 'Duration to Plot';
                lb = 0;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                if(not(isempty(errMsg)))
                    oldPlotDur = getappdata(handles.lvd_EditFitNetModelGUI,'plotDur');
                    handles.durationToPlot.String = fullAccNum2Str(oldPlotDur);
            
                    msgbox(errMsg,'Invalid Plotting Duration','error');
                end
        end

        % Menu selected function: optimizeAllParametersMenu
        function optimizeAllParametersMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to optimizeAllParametersMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                fitNetModel = getappdata(handles.lvd_EditFitNetModelGUI,'fitNetModel');
                varNetWB = fitNetModel.varNetWB;
                varNetWB = true(size(varNetWB));
                fitNetModel.varNetWB = varNetWB;
            
                populateWbParameterDefinition(app, handles, fitNetModel);
        end

        % Button pushed function: plotPolynomialButton
        function plotPolynomialButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to plotPolynomialButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                fitNetModel = getappdata(handles.lvd_EditFitNetModelGUI,'fitNetModel');
                duration = str2double(handles.durationToPlot.String);
            
                hMsg = uiprogressdlg(app.lvd_EditFitNetModelGUI, "Message",'Plotting from neural network, please wait...', "Title","Please Wait", "Indeterminate","on", "Icon","info");
            
                t0 = fitNetModel.getT0();
                t = linspace(t0,t0+duration,500);
                y = NaN(1,length(t));
            
                for(i=1:length(t)) %#ok<*NO4LP> 
                    y(i) = fitNetModel.getValueAtTime(t(i));
                end
            
                y = rad2deg(y);
            
                if(isvalid(hMsg))
                    close(hMsg);
                end
            
                hAx = axes(figure());
                plot(hAx, t, y);
                grid(hAx,'minor');
                xlabel('DT [sec]');
                ylabel('Polynomial Value [deg]');
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % hObject    handle to saveAndCloseButton (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
                app.saveAndClose(handles);
        end

        % Menu selected function: unoptimizeAllParametersMenu
        function unoptimizeAllParametersMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to unoptimizeAllParametersMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                fitNetModel = getappdata(handles.lvd_EditFitNetModelGUI,'fitNetModel');
                varNetWB = fitNetModel.varNetWB;
                varNetWB = false(size(varNetWB));
                fitNetModel.varNetWB = varNetWB;
            
                populateWbParameterDefinition(app, handles, fitNetModel);
        end

        % Value changed function: wbParamLbText
        function wbParamLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % hObject    handle to wbParamLbText (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
            
                % Hints: get(hObject,'String') returns contents of wbParamLbText as text
                %        str2double(get(hObject,'String')) returns contents of wbParamLbText as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
            
                selInd = handles.wbParamListbox.Value;
                fitNetModel = getappdata(handles.lvd_EditFitNetModelGUI,'fitNetModel');
            
                errMsg = {};
            
                value = str2double(get(hObject,'String'));
                enteredStr = get(hObject,'String');
                numberName = 'Weight/Bias Parameter Lower Bound';
                lb = -Inf;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                if(isempty(errMsg))
                    fitNetModel.netWbLb(selInd) = value;
                else
                    hObject.String = fullAccNum2Str(fitNetModel.netWbLb(selInd));
            
                    msgbox(errMsg,'Invalid Weight/Bias Parameter Lower Bound','error');
                end
        end

        % Value changed function: wbParamListbox
        function wbParamListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % hObject    handle to wbParamListbox (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
            
                % Hints: contents = cellstr(get(hObject,'String')) returns wbParamListbox contents as cell array
                %        contents{get(hObject,'Value')} returns selected item from wbParamListbox
                fitNetModel = getappdata(handles.lvd_EditFitNetModelGUI,'fitNetModel');
                populateWbParameterDefinition(app, handles, fitNetModel);
        end

        % Value changed function: wbParamOptCheckbox
        function wbParamOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.wbParamOptCheckbox;
                % hObject    handle to wbParamOptCheckbox (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
            
                % Hint: get(hObject,'Value') returns toggle state of wbParamOptCheckbox
                selInd = handles.wbParamListbox.Value;
                fitNetModel = getappdata(handles.lvd_EditFitNetModelGUI,'fitNetModel');
                fitNetModel.varNetWB(selInd) = logical(hObject.Value);
            
                if(hObject.Value)
                    handles.wbParamLbText.Enable = 'on';
                    handles.wbParamUbText.Enable = 'on';
                else
                    handles.wbParamLbText.Enable = 'off';
                    handles.wbParamUbText.Enable = 'off';
                end
        end

        % Value changed function: wbParamText
        function wbParamText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % hObject    handle to wbParamText (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
            
                % Hints: get(hObject,'String') returns contents of wbParamText as text
                %        str2double(get(hObject,'String')) returns contents of wbParamText as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
            
                selInd = handles.wbParamListbox.Value;
                fitNetModel = getappdata(handles.lvd_EditFitNetModelGUI,'fitNetModel');
            
                errMsg = {};
            
                value = str2double(get(hObject,'String'));
                enteredStr = get(hObject,'String');
                numberName = 'Weight/Bias Parameter';
                lb = -Inf;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                if(isempty(errMsg))
                    fitNetModel.setWbParamAtInd(value, selInd);
            
                    handles.wbParamListbox.String = fitNetModel.getListboxStr();
                else
                    hObject.String = fullAccNum2Str(fitNetModel.getWbParamAtInd(selInd));
            
                    msgbox(errMsg,'Invalid Weight/Bias Parameter','error');
                end
        end

        % Value changed function: wbParamUbText
        function wbParamUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % hObject    handle to wbParamUbText (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
            
                % Hints: get(hObject,'String') returns contents of wbParamUbText as text
                %        str2double(get(hObject,'String')) returns contents of wbParamUbText as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
            
                selInd = handles.wbParamListbox.Value;
                fitNetModel = getappdata(handles.lvd_EditFitNetModelGUI,'fitNetModel');
            
                errMsg = {};
            
                value = str2double(get(hObject,'String'));
                enteredStr = get(hObject,'String');
                numberName = 'Weight/Bias Parameter Upper Bound';
                lb = fitNetModel.netWbLb(selInd);
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                if(isempty(errMsg))
                    fitNetModel.netWbUb(selInd) = value;
                else
                    hObject.String = fullAccNum2Str(fitNetModel.netWbUb(selInd));
            
                    msgbox(errMsg,'Invalid Weight/Bias Parameter Upper Bound','error');
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditFitNetModelGUI = uifigure('Visible', 'off');
            app.lvd_EditFitNetModelGUI.Name = 'Edit Neural Network Model';
            app.lvd_EditFitNetModelGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditFitNetModelGUI.Tag = 'lvd_EditFitNetModelGUI';
            app.GridLayout = uigridlayout(app.lvd_EditFitNetModelGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Neural Network Model';
            app.saveAndCloseButton = uibutton(app.GridLayout, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout2 = uigridlayout(app.uipanel1);
            app.uipanel2 = uipanel(app.GridLayout2);
            app.uipanel2.Title = 'Constant';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout3 = uigridlayout(app.uipanel2);
            app.text3 = uilabel(app.GridLayout3);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Constant Offset';
            app.constText = uieditfield(app.GridLayout3, 'text');
            app.constText.ValueChangedFcn = createCallbackFcn(app, @constText_Callback, true);
            app.constText.Tag = 'constText';
            app.constText.Value = '0.0';
            app.text4 = uilabel(app.GridLayout3);
            app.text4.Tag = 'text4';
            app.text4.Text = 'deg';
            app.constOptCheckbox = uicheckbox(app.GridLayout3);
            app.constOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @constOptCheckbox_Callback, true);
            app.constOptCheckbox.Tag = 'constOptCheckbox';
            app.constOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.constOptCheckbox.Text = 'Opt?';
            app.constOptCheckbox.Value = true;
            app.constLbText = uieditfield(app.GridLayout3, 'text');
            app.constLbText.ValueChangedFcn = createCallbackFcn(app, @constLbText_Callback, true);
            app.constLbText.Tag = 'constLbText';
            app.constLbText.Tooltip = 'Parameter lower bound.';
            app.constLbText.Value = '0.0';
            app.constUbText = uieditfield(app.GridLayout3, 'text');
            app.constUbText.ValueChangedFcn = createCallbackFcn(app, @constUbText_Callback, true);
            app.constUbText.Tag = 'constUbText';
            app.constUbText.Tooltip = 'Parameter upper bound.';
            app.constUbText.Value = '0.0';
            app.text11 = uilabel(app.GridLayout2);
            app.text11.Tag = 'text11';
            app.text11.Tooltip = 'Where dt is time since the start of this steering law plus the time offset, t = t0 + tOffset.';
            app.text11.Text = 'this_quantity = const + network(dt)';
            app.uipanel3 = uipanel(app.GridLayout2);
            app.uipanel3.Title = 'Neural Network Weights and Biases';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout4 = uigridlayout(app.uipanel3);
            app.wbParamListbox = uilistbox(app.GridLayout4);
            app.wbParamListbox.Items = {'Listbox'};
            app.wbParamListbox.ValueChangedFcn = createCallbackFcn(app, @wbParamListbox_Callback, true);
            app.wbParamListbox.Tag = 'wbParamListbox';
            app.wbParamListbox.Value = 'Listbox';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.plotPolynomialButton = uibutton(app.GridLayout6, 'push');
            app.plotPolynomialButton.ButtonPushedFcn = createCallbackFcn(app, @plotPolynomialButton_Callback, true);
            app.plotPolynomialButton.Tag = 'plotPolynomialButton';
            app.plotPolynomialButton.Icon = 'line-chart-data-label.png';
            app.plotPolynomialButton.Text = 'Plot Neural Network Profile';
            app.text13 = uilabel(app.GridLayout6);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Duration to Plot';
            app.text14 = uilabel(app.GridLayout6);
            app.text14.Tag = 'text14';
            app.text14.Text = 'sec';
            app.durationToPlot = uieditfield(app.GridLayout6, 'text');
            app.durationToPlot.ValueChangedFcn = createCallbackFcn(app, @durationToPlot_Callback, true);
            app.durationToPlot.Tag = 'durationToPlot';
            app.durationToPlot.Value = '1.0';
            app.uipanel4 = uipanel(app.GridLayout5);
            app.uipanel4.Title = 'Weight/Bias Definition';
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout7 = uigridlayout(app.uipanel4);
            app.text5 = uilabel(app.GridLayout7);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Parameter';
            app.wbParamText = uieditfield(app.GridLayout7, 'text');
            app.wbParamText.ValueChangedFcn = createCallbackFcn(app, @wbParamText_Callback, true);
            app.wbParamText.Tag = 'wbParamText';
            app.wbParamText.Value = '0.0';
            app.text6 = uilabel(app.GridLayout7);
            app.text6.Tag = 'text6';
            app.text6.Text = '';
            app.wbParamOptCheckbox = uicheckbox(app.GridLayout7);
            app.wbParamOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @wbParamOptCheckbox_Callback, true);
            app.wbParamOptCheckbox.Tag = 'wbParamOptCheckbox';
            app.wbParamOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.wbParamOptCheckbox.Text = 'Opt?';
            app.wbParamOptCheckbox.Value = true;
            app.wbParamLbText = uieditfield(app.GridLayout7, 'text');
            app.wbParamLbText.ValueChangedFcn = createCallbackFcn(app, @wbParamLbText_Callback, true);
            app.wbParamLbText.Tag = 'wbParamLbText';
            app.wbParamLbText.Tooltip = 'Parameter lower bound.';
            app.wbParamLbText.Value = '0.0';
            app.wbParamUbText = uieditfield(app.GridLayout7, 'text');
            app.wbParamUbText.ValueChangedFcn = createCallbackFcn(app, @wbParamUbText_Callback, true);
            app.wbParamUbText.Tag = 'wbParamUbText';
            app.wbParamUbText.Tooltip = 'Parameter upper bound.';
            app.wbParamUbText.Value = '0.0';
            app.listboxContextMenu = uicontextmenu(app.lvd_EditFitNetModelGUI);
            app.listboxContextMenu.Tag = 'listboxContextMenu';
            app.optimizeAllParametersMenu = uimenu(app.listboxContextMenu);
            app.optimizeAllParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @optimizeAllParametersMenu_Callback, true);
            app.optimizeAllParametersMenu.Text = 'Enable Optimization For All Parameters';
            app.optimizeAllParametersMenu.Tag = 'optimizeAllParametersMenu';
            app.unoptimizeAllParametersMenu = uimenu(app.listboxContextMenu);
            app.unoptimizeAllParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @unoptimizeAllParametersMenu_Callback, true);
            app.unoptimizeAllParametersMenu.Text = 'Disable Optimization For All Parameters';
            app.unoptimizeAllParametersMenu.Tag = 'unoptimizeAllParametersMenu';
            % Assign app.listboxContextMenu
            app.wbParamListbox.ContextMenu = app.listboxContextMenu;
            % Show the figure after all components are created
            app.lvd_EditFitNetModelGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditFitNetModelGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditFitNetModelGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditFitNetModelGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditFitNetModelGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditFitNetModelGUI)
        end
    end
end