classdef lvd_EditLinearTangentModelGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditLinearTangentModelGUI  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        uipanel1                       matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        uipanel3                       matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        uipanel4                       matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        bDotUbText                     matlab.ui.control.EditField
        bDotLbText                     matlab.ui.control.EditField
        bDotOptCheckbox                matlab.ui.control.CheckBox
        text18                         matlab.ui.control.Label
        bDotText                       matlab.ui.control.EditField
        text17                         matlab.ui.control.Label
        bUbText                        matlab.ui.control.EditField
        bLbText                        matlab.ui.control.EditField
        bOptCheckbox                   matlab.ui.control.CheckBox
        text16                         matlab.ui.control.Label
        bText                          matlab.ui.control.EditField
        text15                         matlab.ui.control.Label
        aDotUbText                     matlab.ui.control.EditField
        aDotLbText                     matlab.ui.control.EditField
        aDotOptCheckbox                matlab.ui.control.CheckBox
        text8                          matlab.ui.control.Label
        aDotText                       matlab.ui.control.EditField
        text7                          matlab.ui.control.Label
        aUbText                        matlab.ui.control.EditField
        aLbText                        matlab.ui.control.EditField
        aOptCheckbox                   matlab.ui.control.CheckBox
        text6                          matlab.ui.control.Label
        aText                          matlab.ui.control.EditField
        text5                          matlab.ui.control.Label
        GridLayout4                    matlab.ui.container.GridLayout
        text14                         matlab.ui.control.Label
        durationToPlot                 matlab.ui.control.EditField
        text13                         matlab.ui.control.Label
        plotLinearTangentButton        matlab.ui.control.Button
        text11                         matlab.ui.control.Label
        saveAndCloseButton             matlab.ui.control.Button
        text2                          matlab.ui.control.Label
        termListboxMenu                matlab.ui.container.ContextMenu
        copyTermMenu                   matlab.ui.container.Menu
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, linearTangentModel)
            if(~isa(linearTangentModel, 'LinearTangentSelectableModel'))
                uialert(handles.lvd_EditLinearTangentModelGUI, 'This editor requires a selectable linear tangent model (LinearTangentSelectableModel).', 'Invalid Model Type', "Icon","error");
                return;
            end
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            handles.aText.String = fullAccNum2Str(linearTangentModel.a);
            handles.aOptCheckbox.Value = double(linearTangentModel.varA);
            handles.aLbText.String = fullAccNum2Str(linearTangentModel.aLb);
            handles.aUbText.String = fullAccNum2Str(linearTangentModel.aUb);
            aOptCheckbox_Callback(app, evt);

            handles.aDotText.String = fullAccNum2Str(linearTangentModel.a_dot);
            handles.aDotOptCheckbox.Value = double(linearTangentModel.varADot);
            handles.aDotLbText.String = fullAccNum2Str(linearTangentModel.aDotLb);
            handles.aDotUbText.String = fullAccNum2Str(linearTangentModel.aDotUb);
            aDotOptCheckbox_Callback(app, evt);

            handles.bText.String = fullAccNum2Str(linearTangentModel.b);
            handles.bOptCheckbox.Value = double(linearTangentModel.varB);
            handles.bLbText.String = fullAccNum2Str(linearTangentModel.bLb);
            handles.bUbText.String = fullAccNum2Str(linearTangentModel.bUb);
            bOptCheckbox_Callback(app, evt);

            handles.bDotText.String = fullAccNum2Str(linearTangentModel.b_dot);
            handles.bDotOptCheckbox.Value = double(linearTangentModel.varBDot);
            handles.bDotLbText.String = fullAccNum2Str(linearTangentModel.bDotLb);
            handles.bDotUbText.String = fullAccNum2Str(linearTangentModel.bDotUb);
            bDotOptCheckbox_Callback(app, evt);
        end

        function saveAndClose(app, handles)
            app.output.output = {true};
            close(handles.lvd_EditLinearTangentModelGUI);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditLinearTangentModelGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditLinearTangentModelGUI (see VARARGIN)

            % Choose default command line output for lvd_EditLinearTangentModelGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            linearTangentModel = varargin{1};
            setappdata(hObject,'linearTangentModel',linearTangentModel);

            app.output = varargin{2};

            setappdata(hObject,'plotDur',1.0);

            populateGUI(app, handles, linearTangentModel);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditLinearTangentModelGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditLinearTangentModelGUI);
        end

        % Value changed function: aDotLbText
        function aDotLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to aDotLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of aDotLbText as text
            %        str2double(get(hObject,'String')) returns contents of aDotLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            linearTangentModel = getappdata(handles.lvd_EditLinearTangentModelGUI,'linearTangentModel');

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'A Dot Lower Bound';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                linearTangentModel.aDotLb = value;
            else
                hObject.String = fullAccNum2Str(linearTangentModel.aDotLb);

                msgbox(errMsg,'Invalid A Dot Lower Bound','error');
            end
        end

        % Value changed function: aDotOptCheckbox
        function aDotOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.aDotOptCheckbox;
            % hObject    handle to aDotOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of aDotOptCheckbox
            linearTangentModel = getappdata(handles.lvd_EditLinearTangentModelGUI,'linearTangentModel');
            linearTangentModel.varADot = logical(hObject.Value);

            if(hObject.Value)
                handles.aDotLbText.Enable = 'on';
                handles.aDotUbText.Enable = 'on';
            else
                handles.aDotLbText.Enable = 'off';
                handles.aDotUbText.Enable = 'off';
            end
        end

        % Value changed function: aDotText
        function aDotText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to aDotText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of aDotText as text
            %        str2double(get(hObject,'String')) returns contents of aDotText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            linearTangentModel = getappdata(handles.lvd_EditLinearTangentModelGUI,'linearTangentModel');

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'A Dot';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                linearTangentModel.a_dot = value;
            else
                hObject.String = fullAccNum2Str(linearTangentModel.a_dot);

                msgbox(errMsg,'Invalid A Dot','error');
            end
        end

        % Value changed function: aDotUbText
        function aDotUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to aDotUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of aDotUbText as text
            %        str2double(get(hObject,'String')) returns contents of aDotUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            linearTangentModel = getappdata(handles.lvd_EditLinearTangentModelGUI,'linearTangentModel');

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'A Dot Upper Bound';
            lb = linearTangentModel.aDotLb;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                linearTangentModel.aDotUb = value;
            else
                hObject.String = fullAccNum2Str(linearTangentModel.aDotUb);

                msgbox(errMsg,'Invalid A Dot Upper Bound','error');
            end
        end

        % Value changed function: aLbText
        function aLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to aLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of aLbText as text
            %        str2double(get(hObject,'String')) returns contents of aLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            linearTangentModel = getappdata(handles.lvd_EditLinearTangentModelGUI,'linearTangentModel');

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'A Term Lower Bound';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                linearTangentModel.aLb = value;
            else
                hObject.String = fullAccNum2Str(linearTangentModel.aLb);

                msgbox(errMsg,'Invalid A Term Lower Bound','error');
            end
        end

        % Value changed function: aOptCheckbox
        function aOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.aOptCheckbox;
            % hObject    handle to aOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of aOptCheckbox
            linearTangentModel = getappdata(handles.lvd_EditLinearTangentModelGUI,'linearTangentModel');
            linearTangentModel.varA = logical(hObject.Value);

            if(hObject.Value)
                handles.aLbText.Enable = 'on';
                handles.aUbText.Enable = 'on';
            else
                handles.aLbText.Enable = 'off';
                handles.aUbText.Enable = 'off';
            end
        end

        % Value changed function: aText
        function aText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to aText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of aText as text
            %        str2double(get(hObject,'String')) returns contents of aText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            linearTangentModel = getappdata(handles.lvd_EditLinearTangentModelGUI,'linearTangentModel');

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'A Term';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                linearTangentModel.a = value;
            else
                hObject.String = fullAccNum2Str(linearTangentModel.a);

                msgbox(errMsg,'Invalid A Term','error');
            end
        end

        % Value changed function: aUbText
        function aUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to aUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of aUbText as text
            %        str2double(get(hObject,'String')) returns contents of aUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            linearTangentModel = getappdata(handles.lvd_EditLinearTangentModelGUI,'linearTangentModel');

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'A Term Upper Bound';
            lb = linearTangentModel.aLb;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                linearTangentModel.aUb = value;
            else
                hObject.String = fullAccNum2Str(linearTangentModel.aUb);

                msgbox(errMsg,'Invalid A Term Upper Bound','error');
            end
        end

        % Value changed function: bDotLbText
        function bDotLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to bDotLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of bDotLbText as text
            %        str2double(get(hObject,'String')) returns contents of bDotLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            linearTangentModel = getappdata(handles.lvd_EditLinearTangentModelGUI,'linearTangentModel');

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'B Dot Term Lower Bound';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                linearTangentModel.bDotLb = value;
            else
                hObject.String = fullAccNum2Str(linearTangentModel.bDot);

                msgbox(errMsg,'Invalid B Dot Term Lower Bound','error');
            end
        end

        % Value changed function: bDotOptCheckbox
        function bDotOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.bDotOptCheckbox;

            % hObject    handle to bDotOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of bDotOptCheckbox
            linearTangentModel = getappdata(handles.lvd_EditLinearTangentModelGUI,'linearTangentModel');
            linearTangentModel.varBDot = logical(hObject.Value);

            if(hObject.Value)
                handles.bDotLbText.Enable = 'on';
                handles.bDotUbText.Enable = 'on';
            else
                handles.bDotLbText.Enable = 'off';
                handles.bDotUbText.Enable = 'off';
            end
        end

        % Value changed function: bDotText
        function bDotText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to bDotText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of bDotText as text
            %        str2double(get(hObject,'String')) returns contents of bDotText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            linearTangentModel = getappdata(handles.lvd_EditLinearTangentModelGUI,'linearTangentModel');

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'B Dot Term';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                linearTangentModel.b_dot = value;
            else
                hObject.String = fullAccNum2Str(linearTangentModel.b_dot);

                msgbox(errMsg,'Invalid B Dot Term','error');
            end
        end

        % Value changed function: bDotUbText
        function bDotUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to bDotUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of bDotUbText as text
            %        str2double(get(hObject,'String')) returns contents of bDotUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            linearTangentModel = getappdata(handles.lvd_EditLinearTangentModelGUI,'linearTangentModel');

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'B Dot Term Upper Bound';
            lb = linearTangentModel.bDotLb;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                linearTangentModel.bDotUb = value;
            else
                hObject.String = fullAccNum2Str(linearTangentModel.bDotUb);

                msgbox(errMsg,'Invalid B Dot Term Upper Bound','error');
            end
        end

        % Value changed function: bLbText
        function bLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to bLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of bLbText as text
            %        str2double(get(hObject,'String')) returns contents of bLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            linearTangentModel = getappdata(handles.lvd_EditLinearTangentModelGUI,'linearTangentModel');

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'B Term Lower Bound';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                linearTangentModel.bLb = value;
            else
                hObject.String = fullAccNum2Str(linearTangentModel.bLb);

                msgbox(errMsg,'Invalid B Term Lower Bound','error');
            end
        end

        % Value changed function: bOptCheckbox
        function bOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.bOptCheckbox;

            % hObject    handle to bOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of bOptCheckbox
            linearTangentModel = getappdata(handles.lvd_EditLinearTangentModelGUI,'linearTangentModel');
            linearTangentModel.varB = logical(hObject.Value);

            if(hObject.Value)
                handles.bLbText.Enable = 'on';
                handles.bUbText.Enable = 'on';
            else
                handles.bLbText.Enable = 'off';
                handles.bUbText.Enable = 'off';
            end
        end

        % Value changed function: bText
        function bText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to bText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of bText as text
            %        str2double(get(hObject,'String')) returns contents of bText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            linearTangentModel = getappdata(handles.lvd_EditLinearTangentModelGUI,'linearTangentModel');

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'B Term';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                linearTangentModel.b = value;
            else
                hObject.String = fullAccNum2Str(linearTangentModel.b);

                msgbox(errMsg,'Invalid B Term','error');
            end
        end

        % Value changed function: bUbText
        function bUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to bUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of bUbText as text
            %        str2double(get(hObject,'String')) returns contents of bUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            linearTangentModel = getappdata(handles.lvd_EditLinearTangentModelGUI,'linearTangentModel');

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'B Term Upper Bound';
            lb = linearTangentModel.bLb;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                linearTangentModel.bUb = value;
            else
                hObject.String = fullAccNum2Str(linearTangentModel.bUb);

                msgbox(errMsg,'Invalid B Term Upper Bound','error');
            end
        end

        % Value changed function: durationToPlot
        function durationToPlot_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to durationToPlot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of durationToPlot as text
            %        str2double(get(hObject,'String')) returns contents of durationToPlot as a double
            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Duration to Plot';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(not(isempty(errMsg)))
                oldPlotDur = getappdata(handles.lvd_EditLinearTangentModelGUI,'plotDur');
                handles.durationToPlot.String = fullAccNum2Str(oldPlotDur);

                msgbox(errMsg,'Invalid Plotting Duration','error');
            end
        end

        % Button pushed function: plotLinearTangentButton
        function plotLinearTangentButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to plotLinearTangentButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            linearTangentModel = getappdata(handles.lvd_EditLinearTangentModelGUI,'linearTangentModel');
            duration = str2double(handles.durationToPlot.String);

            t0 = linearTangentModel.getT0();
            t = linspace(t0,t0+duration,500);
            y = NaN(1,length(t));

            for(i=1:length(t)) %#ok<*NO4LP>
                y(i) = linearTangentModel.getValueAtTime(t(i));
            end

            y = rad2deg(y);

            hAx = axes(figure());
            plot(hAx, t, y);
            grid(hAx,'minor');
            xlabel('DT [sec]');
            ylabel('Polynomial Value [deg]');
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.saveAndClose(handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditLinearTangentModelGUI = uifigure('Visible', 'off');
            app.lvd_EditLinearTangentModelGUI.Name = 'Edit Linear Tangent Model';
            app.lvd_EditLinearTangentModelGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditLinearTangentModelGUI.Tag = 'lvd_EditLinearTangentModelGUI';
            app.GridLayout = uigridlayout(app.lvd_EditLinearTangentModelGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Linear Tangent Model';
            app.saveAndCloseButton = uibutton(app.GridLayout, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout2 = uigridlayout(app.uipanel1);
            app.text11 = uilabel(app.GridLayout2);
            app.text11.Tag = 'text11';
            app.text11.Tooltip = 'Where dt is time since the start of this steering law plus the time offset, t = t0 + tOffset.';
            app.text11.Text = 'this_quantity = atan((a + a_dot * t) * t + (b + b_dot * t))';
            app.uipanel3 = uipanel(app.GridLayout2);
            app.uipanel3.Title = 'Linear Tangent Model';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout3 = uigridlayout(app.uipanel3);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.plotLinearTangentButton = uibutton(app.GridLayout4, 'push');
            app.plotLinearTangentButton.ButtonPushedFcn = createCallbackFcn(app, @plotLinearTangentButton_Callback, true);
            app.plotLinearTangentButton.Tag = 'plotLinearTangentButton';
            app.plotLinearTangentButton.Icon = 'line-chart-data-label.png';
            app.plotLinearTangentButton.Text = 'Plot Linear Tangent Model';
            app.text13 = uilabel(app.GridLayout4);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Duration to Plot';
            app.durationToPlot = uieditfield(app.GridLayout4, 'text');
            app.durationToPlot.ValueChangedFcn = createCallbackFcn(app, @durationToPlot_Callback, true);
            app.durationToPlot.Tag = 'durationToPlot';
            app.durationToPlot.Value = '1.0';
            app.text14 = uilabel(app.GridLayout4);
            app.text14.Tag = 'text14';
            app.text14.Text = 'sec';
            app.uipanel4 = uipanel(app.GridLayout3);
            app.uipanel4.Title = 'Linear Tangent Term Definitions';
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout5 = uigridlayout(app.uipanel4);
            app.text5 = uilabel(app.GridLayout5);
            app.text5.Tag = 'text5';
            app.text5.Text = 'A Term';
            app.aText = uieditfield(app.GridLayout5, 'text');
            app.aText.ValueChangedFcn = createCallbackFcn(app, @aText_Callback, true);
            app.aText.Tag = 'aText';
            app.aText.Value = '0.0';
            app.text6 = uilabel(app.GridLayout5);
            app.text6.Tag = 'text6';
            app.text6.Text = '1/s';
            app.aOptCheckbox = uicheckbox(app.GridLayout5);
            app.aOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @aOptCheckbox_Callback, true);
            app.aOptCheckbox.Tag = 'aOptCheckbox';
            app.aOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.aOptCheckbox.Text = 'Opt?';
            app.aOptCheckbox.Value = true;
            app.aLbText = uieditfield(app.GridLayout5, 'text');
            app.aLbText.ValueChangedFcn = createCallbackFcn(app, @aLbText_Callback, true);
            app.aLbText.Tag = 'aLbText';
            app.aLbText.Tooltip = 'Parameter lower bound.';
            app.aLbText.Value = '0.0';
            app.aUbText = uieditfield(app.GridLayout5, 'text');
            app.aUbText.ValueChangedFcn = createCallbackFcn(app, @aUbText_Callback, true);
            app.aUbText.Tag = 'aUbText';
            app.aUbText.Tooltip = 'Parameter upper bound.';
            app.aUbText.Value = '0.0';
            app.text7 = uilabel(app.GridLayout5);
            app.text7.Tag = 'text7';
            app.text7.Text = 'A Dot Term';
            app.aDotText = uieditfield(app.GridLayout5, 'text');
            app.aDotText.ValueChangedFcn = createCallbackFcn(app, @aDotText_Callback, true);
            app.aDotText.Tag = 'aDotText';
            app.aDotText.Value = '0.0';
            app.text8 = uilabel(app.GridLayout5);
            app.text8.Tag = 'text8';
            app.text8.Text = '1/s/s';
            app.aDotOptCheckbox = uicheckbox(app.GridLayout5);
            app.aDotOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @aDotOptCheckbox_Callback, true);
            app.aDotOptCheckbox.Tag = 'aDotOptCheckbox';
            app.aDotOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.aDotOptCheckbox.Text = 'Opt?';
            app.aDotOptCheckbox.Value = true;
            app.aDotLbText = uieditfield(app.GridLayout5, 'text');
            app.aDotLbText.ValueChangedFcn = createCallbackFcn(app, @aDotLbText_Callback, true);
            app.aDotLbText.Tag = 'aDotLbText';
            app.aDotLbText.Tooltip = 'Parameter lower bound.';
            app.aDotLbText.Value = '0.0';
            app.aDotUbText = uieditfield(app.GridLayout5, 'text');
            app.aDotUbText.ValueChangedFcn = createCallbackFcn(app, @aDotUbText_Callback, true);
            app.aDotUbText.Tag = 'aDotUbText';
            app.aDotUbText.Tooltip = 'Parameter upper bound.';
            app.aDotUbText.Value = '0.0';
            app.text15 = uilabel(app.GridLayout5);
            app.text15.Tag = 'text15';
            app.text15.Text = 'B Term';
            app.bText = uieditfield(app.GridLayout5, 'text');
            app.bText.ValueChangedFcn = createCallbackFcn(app, @bText_Callback, true);
            app.bText.Tag = 'bText';
            app.bText.Value = '0.0';
            app.text16 = uilabel(app.GridLayout5);
            app.text16.Tag = 'text16';
            app.text16.Text = '';
            app.bOptCheckbox = uicheckbox(app.GridLayout5);
            app.bOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @bOptCheckbox_Callback, true);
            app.bOptCheckbox.Tag = 'bOptCheckbox';
            app.bOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.bOptCheckbox.Text = 'Opt?';
            app.bOptCheckbox.Value = true;
            app.bLbText = uieditfield(app.GridLayout5, 'text');
            app.bLbText.ValueChangedFcn = createCallbackFcn(app, @bLbText_Callback, true);
            app.bLbText.Tag = 'bLbText';
            app.bLbText.Tooltip = 'Parameter lower bound.';
            app.bLbText.Value = '0.0';
            app.bUbText = uieditfield(app.GridLayout5, 'text');
            app.bUbText.ValueChangedFcn = createCallbackFcn(app, @bUbText_Callback, true);
            app.bUbText.Tag = 'bUbText';
            app.bUbText.Tooltip = 'Parameter upper bound.';
            app.bUbText.Value = '0.0';
            app.text17 = uilabel(app.GridLayout5);
            app.text17.Tag = 'text17';
            app.text17.Text = 'B Dot Term';
            app.bDotText = uieditfield(app.GridLayout5, 'text');
            app.bDotText.ValueChangedFcn = createCallbackFcn(app, @bDotText_Callback, true);
            app.bDotText.Tag = 'bDotText';
            app.bDotText.Value = '0.0';
            app.text18 = uilabel(app.GridLayout5);
            app.text18.Tag = 'text18';
            app.text18.Text = '1/s';
            app.bDotOptCheckbox = uicheckbox(app.GridLayout5);
            app.bDotOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @bDotOptCheckbox_Callback, true);
            app.bDotOptCheckbox.Tag = 'bDotOptCheckbox';
            app.bDotOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.bDotOptCheckbox.Text = 'Opt?';
            app.bDotOptCheckbox.Value = true;
            app.bDotLbText = uieditfield(app.GridLayout5, 'text');
            app.bDotLbText.ValueChangedFcn = createCallbackFcn(app, @bDotLbText_Callback, true);
            app.bDotLbText.Tag = 'bDotLbText';
            app.bDotLbText.Tooltip = 'Parameter lower bound.';
            app.bDotLbText.Value = '0.0';
            app.bDotUbText = uieditfield(app.GridLayout5, 'text');
            app.bDotUbText.ValueChangedFcn = createCallbackFcn(app, @bDotUbText_Callback, true);
            app.bDotUbText.Tag = 'bDotUbText';
            app.bDotUbText.Tooltip = 'Parameter upper bound.';
            app.bDotUbText.Value = '0.0';
            app.termListboxMenu = uicontextmenu(app.lvd_EditLinearTangentModelGUI);
            app.termListboxMenu.Tag = 'termListboxMenu';
            app.copyTermMenu = uimenu(app.termListboxMenu);
            app.copyTermMenu.Text = 'Copy Term';
            app.copyTermMenu.Tag = 'copyTermMenu';
            % Show the figure after all components are created
            app.lvd_EditLinearTangentModelGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditLinearTangentModelGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditLinearTangentModelGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditLinearTangentModelGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditLinearTangentModelGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditLinearTangentModelGUI)
        end
    end
end