classdef lvd_EditSteeringModelsSet_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditSteeringModelsUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        SteeringModelPanel          matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        ModelDescLabel              matlab.ui.control.Label
        EditSteeringModelButton     matlab.ui.control.Button
        GridLayout4                 matlab.ui.container.GridLayout
        SteeringModelLabel          matlab.ui.control.Label
        SteeringModelCombo          matlab.ui.control.DropDown
        GridLayout2                 matlab.ui.container.GridLayout
        cancelButton                matlab.ui.control.Button
        saveCloseButton             matlab.ui.control.Button
        text2                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput
        lvdData LvdData
        steeringModels SteeringModelsSet
        allowContinuity(1,1) logical = true;
    end
    
    methods (Access = private)
        function populateGUI(app)
            [listBoxStr, m] = SteerModelTypeEnum.getListBoxStr();
            app.SteeringModelCombo.Items = listBoxStr;
            app.SteeringModelCombo.ItemsData = m;
            app.SteeringModelCombo.Value = app.steeringModels.selectedModel.getSteeringModelTypeEnum();

            app.ModelDescLabel.Text = app.SteeringModelCombo.Value.desc;
        end

        function saveAndClose(app)
            enum = app.SteeringModelCombo.Value;
            switch enum
                case SteerModelTypeEnum.PolyAngles
                    app.steeringModels.selectedModel = app.steeringModels.polySteering;
                    otherModels = [app.steeringModels.quatInterp, app.steeringModels.genericSelectableModels, app.steeringModels.tabularQuatInterp];

                case SteerModelTypeEnum.QuaterionInterp
                    app.steeringModels.selectedModel = app.steeringModels.quatInterp;
                    otherModels = [app.steeringModels.polySteering, app.steeringModels.genericSelectableModels, app.steeringModels.tabularQuatInterp];

                case SteerModelTypeEnum.SelectableModelAngles
                    app.steeringModels.selectedModel = app.steeringModels.genericSelectableModels;
                    otherModels = [app.steeringModels.polySteering, app.steeringModels.quatInterp, app.steeringModels.tabularQuatInterp];

                case SteerModelTypeEnum.TabularQuaterionInterp
                    app.steeringModels.selectedModel = app.steeringModels.tabularQuatInterp;
                    otherModels = [app.steeringModels.polySteering, app.steeringModels.quatInterp, app.steeringModels.genericSelectableModels];

                otherwise
                    error('Unknown steering model type: %s', enum.name);
            end

            %need to turn off optimization variables for other models
            for(i=1:length(otherModels)) %#ok<*NO4LP> 
                oModelOptVar = otherModels(i).getExistingOptVar();

                if(not(isempty(oModelOptVar)))
                    useTf = oModelOptVar.getUseTfForVariable();
                    oModelOptVar.setUseTfForVariable(false(size(useTf)));
                end
            end

            app.output.output{1} = true;
            close(app.EditSteeringModelsUIFigure);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, steeringModels, lvdData, out, allowContinuity)
            centerUIFigure(app.EditSteeringModelsUIFigure);
            applySelectedThemeToApp(app);
            
            app.steeringModels = steeringModels;
            app.lvdData = lvdData;
            app.output = out;
            app.allowContinuity = allowContinuity;

            app.populateGUI();

            uiwait(app.EditSteeringModelsUIFigure);
        end

        % Button pushed function: EditSteeringModelButton
        function EditSteeringModelButtonPushed(app, event)
            lv = app.lvdData.launchVehicle;
            
            enum = app.SteeringModelCombo.Value;
            switch enum
                case SteerModelTypeEnum.PolyAngles
                    [~, steeringModel] = app.steeringModels.polySteering.openEditSteeringModelUI(lv, app.allowContinuity);
                    app.steeringModels.polySteering = steeringModel;

                case SteerModelTypeEnum.QuaterionInterp
                    [~, steeringModel] = app.steeringModels.quatInterp.openEditSteeringModelUI(lv, app.allowContinuity);
                    app.steeringModels.quatInterp = steeringModel;

                case SteerModelTypeEnum.SelectableModelAngles
                    [~, steeringModel] = app.steeringModels.genericSelectableModels.openEditSteeringModelUI(lv, app.allowContinuity);
                    app.steeringModels.genericSelectableModels = steeringModel;

                case SteerModelTypeEnum.TabularQuaterionInterp
                    [~, steeringModel] = app.steeringModels.tabularQuatInterp.openEditSteeringModelUI(lv, app.allowContinuity);
                    app.steeringModels.tabularQuatInterp = steeringModel;

                otherwise
                    error('Unknown steering model type: %s', enum.name);
            end
        end

        % Button pushed function: saveCloseButton
        function saveCloseButtonPushed(app, event)
            errMsg = {};
            
            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.EditSteeringModelsUIFigure, errMsg, 'Errors were found while editing the steering model.', "Icon","error");
            end 
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            app.output.output = {false};
            close(app.EditSteeringModelsUIFigure);
        end

        % Value changed function: SteeringModelCombo
        function SteeringModelComboValueChanged(app, event)
            enum = app.SteeringModelCombo.Value;
            app.ModelDescLabel.Text = enum.desc;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditSteeringModelsUIFigure = uifigure('Visible', 'off');
            app.EditSteeringModelsUIFigure.Name = 'Set Steering Model';
            app.EditSteeringModelsUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout = uigridlayout(app.EditSteeringModelsUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Steering Model';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveCloseButtonPushed, true);
            app.saveCloseButton.Tag = 'saveCloseButton';
            app.saveCloseButton.Icon = 'save-file.png';
            app.saveCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.SteeringModelPanel = uipanel(app.GridLayout);
            app.SteeringModelPanel.Title = 'Steering Model';
            app.GridLayout3 = uigridlayout(app.SteeringModelPanel);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.SteeringModelCombo = uidropdown(app.GridLayout4);
            app.SteeringModelCombo.ValueChangedFcn = createCallbackFcn(app, @SteeringModelComboValueChanged, true);
            app.SteeringModelLabel = uilabel(app.GridLayout4);
            app.SteeringModelLabel.Text = 'Steering Model';
            app.EditSteeringModelButton = uibutton(app.GridLayout3, 'push');
            app.EditSteeringModelButton.ButtonPushedFcn = createCallbackFcn(app, @EditSteeringModelButtonPushed, true);
            app.EditSteeringModelButton.Icon = 'steering-wheel.png';
            app.EditSteeringModelButton.Text = 'Edit Steering Model';
            app.ModelDescLabel = uilabel(app.GridLayout3);
            % Show the figure after all components are created
            app.EditSteeringModelsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditSteeringModelsSet_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditSteeringModelsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditSteeringModelsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditSteeringModelsUIFigure)
        end
    end
end