classdef lvd_EditSumOfPolynomialTermsModelGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditSumOfPolynomialTermsModelGUI  matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        uipanel1              matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        uipanel3              matlab.ui.container.Panel
        GridLayout4           matlab.ui.container.GridLayout
        GridLayout7           matlab.ui.container.GridLayout
        uipanel4              matlab.ui.container.Panel
        GridLayout10          matlab.ui.container.GridLayout
        expUbText             matlab.ui.control.EditField
        coeffUbText           matlab.ui.control.EditField
        expLbText             matlab.ui.control.EditField
        coeffLbText           matlab.ui.control.EditField
        expOptCheckbox        matlab.ui.control.CheckBox
        coeffOptCheckbox      matlab.ui.control.CheckBox
        text8                 matlab.ui.control.Label
        text6                 matlab.ui.control.Label
        expText               matlab.ui.control.EditField
        coeffText             matlab.ui.control.EditField
        text7                 matlab.ui.control.Label
        text5                 matlab.ui.control.Label
        GridLayout8           matlab.ui.container.GridLayout
        GridLayout9           matlab.ui.container.GridLayout
        text14                matlab.ui.control.Label
        durationToPlot        matlab.ui.control.EditField
        text13                matlab.ui.control.Label
        plotPolynomialButton  matlab.ui.control.Button
        GridLayout5           matlab.ui.container.GridLayout
        termListbox           matlab.ui.control.ListBox
        GridLayout6           matlab.ui.container.GridLayout
        removeTermButton      matlab.ui.control.Button
        addTermButton         matlab.ui.control.Button
        uipanel2              matlab.ui.container.Panel
        GridLayout3           matlab.ui.container.GridLayout
        constUbText           matlab.ui.control.EditField
        constLbText           matlab.ui.control.EditField
        constOptCheckbox      matlab.ui.control.CheckBox
        text4                 matlab.ui.control.Label
        constText             matlab.ui.control.EditField
        text3                 matlab.ui.control.Label
        text12                matlab.ui.control.Label
        text11                matlab.ui.control.Label
        saveAndCloseButton    matlab.ui.control.Button
        text2                 matlab.ui.control.Label
        termListboxMenu       matlab.ui.container.ContextMenu
        copyTermMenu          matlab.ui.container.Menu
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function termModel = getSelectedTermModel(app, handles, sumOfPolyTerms)
            termModel = app.termListbox.Value;
            
%             numSines = sumOfPolyTerms.getNumTerms();
% 
%             if(numSines > 0)
%                 selTermInd = handles.termListbox.Value;
%                 termModel = sumOfPolyTerms.getTermAtInd(selTermInd);
%             else
%                 termModel = PolynominalTermModel.empty(1,0);
%             end
        end

        function populateGUI(app, handles, sumOfPolyTerms)
            handles.constText.String = fullAccNum2Str(rad2deg(sumOfPolyTerms.const));
            handles.constOptCheckbox.Value = double(sumOfPolyTerms.varConst);
            handles.constLbText.String = fullAccNum2Str(rad2deg(sumOfPolyTerms.constLb));
            handles.constUbText.String = fullAccNum2Str(rad2deg(sumOfPolyTerms.constUb));

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            constOptCheckbox_Callback(app, evt);

            [termListboxStr, terms] = sumOfPolyTerms.getListboxStr();
            app.termListbox.Items = termListboxStr;
            app.termListbox.ItemsData = terms;
            
            if(~isempty(termListboxStr))
                app.termListbox.Value =terms(1);
            end

            populateTermDefinition(app, handles, sumOfPolyTerms);
        end

        function populateTermDefinition(app, handles, sumOfPolyTerms)
            termModel = getSelectedTermModel(app, handles, sumOfPolyTerms);

            if(not(isempty(termModel)))
                setTermModelWidgetsEnable(app, handles, 'on');

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                
                handles.coeffText.String = fullAccNum2Str(rad2deg(termModel.coeff));
                handles.coeffOptCheckbox.Value = double(termModel.varCoeff);
                handles.coeffLbText.String = fullAccNum2Str(rad2deg(termModel.coeffLb));
                handles.coeffUbText.String = fullAccNum2Str(rad2deg(termModel.coeffUb));
                coeffOptCheckbox_Callback(app, evt);

                handles.expText.String = fullAccNum2Str(termModel.exponent);
                handles.expOptCheckbox.Value = double(termModel.varExp);
                handles.expLbText.String = fullAccNum2Str(termModel.expLb);
                handles.expUbText.String = fullAccNum2Str(termModel.expUb);
                expOptCheckbox_Callback(app, evt);

            else
                setTermModelWidgetsEnable(app, handles, 'off');
            end
        end

        function setTermModelWidgetsEnable(app, handles, enableStr)
            handles.coeffText.Enable = enableStr;
            handles.coeffOptCheckbox.Enable = enableStr;
            handles.coeffLbText.Enable = enableStr;
            handles.coeffUbText.Enable = enableStr;

            handles.expText.Enable = enableStr;
            handles.expOptCheckbox.Enable = enableStr;
            handles.expLbText.Enable = enableStr;
            handles.expUbText.Enable = enableStr;
        end


        function saveAndClose(app, handles)
            app.output.output = {true};
            close(handles.lvd_EditSumOfPolynomialTermsModelGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditSumOfPolynomialTermsModelGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditSumOfPolynomialTermsModelGUI (see VARARGIN)

            % Choose default command line output for lvd_EditSumOfPolynomialTermsModelGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            sumOfPolyTerms = varargin{1};
            setappdata(hObject,'sumOfPolyTerms',sumOfPolyTerms);

            app.output = varargin{2};

            setappdata(hObject,'plotDur',1.0);

            populateGUI(app, handles, sumOfPolyTerms);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditSumOfPolynomialTermsModelGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditSumOfPolynomialTermsModelGUI);
        end

        % Button pushed function: addTermButton
        function addTermButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addTermButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            sumOfPolyTerms = getappdata(handles.lvd_EditSumOfPolynomialTermsModelGUI,'sumOfPolyTerms');

            termModel = PolynominalTermModel(0, 1, 1);
            sumOfPolyTerms.addTerm(termModel);

            [termListboxStr, terms] = sumOfPolyTerms.getListboxStr();
            app.termListbox.Items = termListboxStr;
            app.termListbox.ItemsData = terms;
            
            app.termListbox.Value = terms(end);

            populateTermDefinition(app, handles, sumOfPolyTerms);
        end

        % Value changed function: coeffLbText
        function coeffLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to coeffLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of coeffLbText as text
            %        str2double(get(hObject,'String')) returns contents of coeffLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            sumOfPolyTerms = getappdata(handles.lvd_EditSumOfPolynomialTermsModelGUI,'sumOfPolyTerms');
            termModel = getSelectedTermModel(app, handles, sumOfPolyTerms);

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Term Coefficient Lower Bound';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                termModel.coeffLb = deg2rad(value);
            else
                hObject.String = fullAccNum2Str(rad2deg(termModel.coeffLb));

                msgbox(errMsg,'Invalid Term Coefficient Lower Bound','error');
            end
        end

        % Value changed function: coeffOptCheckbox
        function coeffOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.coeffOptCheckbox;
            
            % hObject    handle to coeffOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of coeffOptCheckbox
            sumOfPolyTerms = getappdata(handles.lvd_EditSumOfPolynomialTermsModelGUI,'sumOfPolyTerms');
            termModel = getSelectedTermModel(app, handles, sumOfPolyTerms);
            termModel.varCoeff = logical(hObject.Value);

            if(hObject.Value)
                handles.coeffLbText.Enable = 'on';
                handles.coeffUbText.Enable = 'on';
            else
                handles.coeffLbText.Enable = 'off';
                handles.coeffUbText.Enable = 'off';
            end
        end

        % Value changed function: coeffText
        function coeffText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to coeffText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of coeffText as text
            %        str2double(get(hObject,'String')) returns contents of coeffText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            sumOfPolyTerms = getappdata(handles.lvd_EditSumOfPolynomialTermsModelGUI,'sumOfPolyTerms');
            termModel = getSelectedTermModel(app, handles, sumOfPolyTerms);

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Term Coefficient';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                termModel.coeff = deg2rad(value);

                [termListboxStr, terms] = sumOfPolyTerms.getListboxStr();
                app.termListbox.Items = termListboxStr;
                app.termListbox.ItemsData = terms;
            else
                hObject.String = fullAccNum2Str(termModel.coeff);

                msgbox(errMsg,'Invalid Term Coefficient','error');
            end
        end

        % Value changed function: coeffUbText
        function coeffUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to coeffUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of coeffUbText as text
            %        str2double(get(hObject,'String')) returns contents of coeffUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            sumOfPolyTerms = getappdata(handles.lvd_EditSumOfPolynomialTermsModelGUI,'sumOfPolyTerms');
            termModel = getSelectedTermModel(app, handles, sumOfPolyTerms);

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Term Coefficient Upper Bound';
            lb = termModel.coeffLb;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                termModel.coeffUb = deg2rad(value);
            else
                hObject.String = fullAccNum2Str(rad2deg(termModel.coeffUb));

                msgbox(errMsg,'Invalid Term Coefficient Upper Bound','error');
            end
        end

        % Value changed function: constLbText
        function constLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to constLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of constLbText as text
            %        str2double(get(hObject,'String')) returns contents of constLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            sumOfPolyTerms = getappdata(handles.lvd_EditSumOfPolynomialTermsModelGUI,'sumOfPolyTerms');

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Sine Constant Offset Lower Bound';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                sumOfPolyTerms.constLb = deg2rad(value);
            else
                hObject.String = fullAccNum2Str(sumOfPolyTerms.constLb);

                msgbox(errMsg,'Invalid Sine Constant Offset Lower Bound','error');
            end
        end

        % Value changed function: constOptCheckbox
        function constOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.constOptCheckbox;
            
            % hObject    handle to constOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of constOptCheckbox
            sumOfPolyTerms = getappdata(handles.lvd_EditSumOfPolynomialTermsModelGUI,'sumOfPolyTerms');
            sumOfPolyTerms.varConst = logical(hObject.Value);

            if(hObject.Value)
                handles.constLbText.Enable = 'on';
                handles.constUbText.Enable = 'on';
            else
                handles.constLbText.Enable = 'off';
                handles.constUbText.Enable = 'off';
            end
        end

        % Value changed function: constText
        function constText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to constText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of constText as text
            %        str2double(get(hObject,'String')) returns contents of constText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            sumOfPolyTerms = getappdata(handles.lvd_EditSumOfPolynomialTermsModelGUI,'sumOfPolyTerms');

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Constant Offset';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                sumOfPolyTerms.const = deg2rad(value);
            else
                hObject.String = fullAccNum2Str(rad2deg(sumOfPolyTerms.const));

                msgbox(errMsg,'Invalid Constant Offset','error');
            end
        end

        % Value changed function: constUbText
        function constUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to constUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of constUbText as text
            %        str2double(get(hObject,'String')) returns contents of constUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            sumOfPolyTerms = getappdata(handles.lvd_EditSumOfPolynomialTermsModelGUI,'sumOfPolyTerms');

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Sine Constant Offset Upper Bound';
            lb = sumOfPolyTerms.constLb;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                sumOfPolyTerms.constUb = deg2rad(value);
            else
                hObject.String = fullAccNum2Str(sumOfPolyTerms.constUb);

                msgbox(errMsg,'Invalid Sine Constant Offset Upper Bound','error');
            end
        end

        % Menu selected function: copyTermMenu
        function copyTermMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to copyTermMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            sumOfPolyTerms = getappdata(handles.lvd_EditSumOfPolynomialTermsModelGUI,'sumOfPolyTerms');
            termModel = getSelectedTermModel(app, handles, sumOfPolyTerms);

            newTermModel = termModel.deepCopy();
            sumOfPolyTerms.addTerm(newTermModel);

            [termListboxStr, terms] = sumOfPolyTerms.getListboxStr();
            app.termListbox.Items = termListboxStr;
            app.termListbox.ItemsData = terms;
            
            app.termListbox.Value = terms(end);

            populateTermDefinition(app, handles, sumOfPolyTerms);
        end

        % Value changed function: durationToPlot
        function durationToPlot_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to durationToPlot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of durationToPlot as text
            %        str2double(get(hObject,'String')) returns contents of durationToPlot as a double
            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Duration to Plot';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(not(isempty(errMsg)))
                oldPlotDur = getappdata(handles.lvd_EditSumOfPolynomialTermsModelGUI,'plotDur');
                handles.durationToPlot.String = fullAccNum2Str(oldPlotDur);

                msgbox(errMsg,'Invalid Plotting Duration','error');
            end
        end

        % Value changed function: expLbText
        function expLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to expLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of expLbText as text
            %        str2double(get(hObject,'String')) returns contents of expLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            sumOfPolyTerms = getappdata(handles.lvd_EditSumOfPolynomialTermsModelGUI,'sumOfPolyTerms');
            termModel = getSelectedTermModel(app, handles, sumOfPolyTerms);

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Term Exponent Lower Bound';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                termModel.expLb = value;
            else
                hObject.String = fullAccNum2Str(termModel.expLb);

                msgbox(errMsg,'Invalid Term Exponent Lower Bound','error');
            end
        end

        % Value changed function: expOptCheckbox
        function expOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.expOptCheckbox;
            
            % hObject    handle to expOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of expOptCheckbox
            sumOfPolyTerms = getappdata(handles.lvd_EditSumOfPolynomialTermsModelGUI,'sumOfPolyTerms');
            termModel = getSelectedTermModel(app, handles, sumOfPolyTerms);
            termModel.varExp = logical(hObject.Value);

            if(hObject.Value)
                handles.expLbText.Enable = 'on';
                handles.expUbText.Enable = 'on';
            else
                handles.expLbText.Enable = 'off';
                handles.expUbText.Enable = 'off';
            end
        end

        % Value changed function: expText
        function expText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to expText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of expText as text
            %        str2double(get(hObject,'String')) returns contents of expText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            sumOfPolyTerms = getappdata(handles.lvd_EditSumOfPolynomialTermsModelGUI,'sumOfPolyTerms');
            termModel = getSelectedTermModel(app, handles, sumOfPolyTerms);

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Term Exponent';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                termModel.exponent = value;

                [termListboxStr, terms] = sumOfPolyTerms.getListboxStr();
                app.termListbox.Items = termListboxStr;
                app.termListbox.ItemsData = terms;
            else
                hObject.String = fullAccNum2Str(termModel.exponent);

                msgbox(errMsg,'Invalid Term Exponent','error');
            end
        end

        % Value changed function: expUbText
        function expUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to expUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of expUbText as text
            %        str2double(get(hObject,'String')) returns contents of expUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            sumOfPolyTerms = getappdata(handles.lvd_EditSumOfPolynomialTermsModelGUI,'sumOfPolyTerms');
            termModel = getSelectedTermModel(app, handles, sumOfPolyTerms);

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Term Exponent Upper Bound';
            lb = termModel.expLb;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                termModel.expUb = value;
            else
                hObject.String = fullAccNum2Str(termModel.expUb);

                msgbox(errMsg,'Invalid Term Exponent Upper Bound','error');
            end
        end

        % Button pushed function: plotPolynomialButton
        function plotPolynomialButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to plotPolynomialButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            sumOfPolyTerms = getappdata(handles.lvd_EditSumOfPolynomialTermsModelGUI,'sumOfPolyTerms');
            duration = str2double(handles.durationToPlot.String);

            t0 = sumOfPolyTerms.getT0();
            t = linspace(t0,t0+duration,500);
            y = NaN(1,length(t));

            for(i=1:length(t)) %#ok<*NO4LP> 
                y(i) = sumOfPolyTerms.getValueAtTime(t(i));
            end

            y = rad2deg(y);

            hAx = axes(figure());
            plot(hAx, t, y);
            grid(hAx,'minor');
            xlabel('DT [sec]');
            ylabel('Polynomial Value [deg]');
        end

        % Button pushed function: removeTermButton
        function removeTermButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeTermButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            sumOfPolyTerms = getappdata(handles.lvd_EditSumOfPolynomialTermsModelGUI,'sumOfPolyTerms');
            termModel = getSelectedTermModel(app, handles, sumOfPolyTerms);

            if(not(isempty(termModel)))
                sumOfPolyTerms.removeTerm(termModel);
            end

            [termListboxStr, terms] = sumOfPolyTerms.getListboxStr();
            app.termListbox.Items = termListboxStr;
            app.termListbox.ItemsData = terms;

%             if(handles.termListbox.Value > length(listBoxStr))
%                 handles.termListbox.Value = length(listBoxStr);
%             end

            populateTermDefinition(app, handles, sumOfPolyTerms);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.saveAndClose(handles);
        end

        % Value changed function: termListbox
        function termListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to termListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns termListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from termListbox
            sumOfPolyTerms = getappdata(handles.lvd_EditSumOfPolynomialTermsModelGUI,'sumOfPolyTerms');
            populateTermDefinition(app, handles, sumOfPolyTerms);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditSumOfPolynomialTermsModelGUI = uifigure('Visible', 'off');
            app.lvd_EditSumOfPolynomialTermsModelGUI.Name = 'Edit Generic Polynomial Model';
            app.lvd_EditSumOfPolynomialTermsModelGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditSumOfPolynomialTermsModelGUI.Tag = 'lvd_EditSumOfPolynomialTermsModelGUI';
            app.GridLayout = uigridlayout(app.lvd_EditSumOfPolynomialTermsModelGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Generic Polynomial Model';
            app.saveAndCloseButton = uibutton(app.GridLayout, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout2 = uigridlayout(app.uipanel1);
            app.text11 = uilabel(app.GridLayout2);
            app.text11.Tag = 'text11';
            app.text11.Tooltip = 'Where dt is time since the start of this steering law plus the time offset, t = t0 + tOffset.';
            app.text11.Text = 'this_quantity = const + coeff1 *  dt ^ exp1 + ... ';
            app.text12 = uilabel(app.GridLayout2);
            app.text12.Tag = 'text12';
            app.text12.Tooltip = 'Where dt is time since the start of this steering law plus the time offset, t = t0 + tOffset.';
            app.text12.Text = '                 coeffN *  dt ^ expN';
            app.uipanel2 = uipanel(app.GridLayout2);
            app.uipanel2.Title = 'Constant';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout3 = uigridlayout(app.uipanel2);
            app.text3 = uilabel(app.GridLayout3);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Constant Offset';
            app.constText = uieditfield(app.GridLayout3, 'text');
            app.constText.ValueChangedFcn = createCallbackFcn(app, @constText_Callback, true);
            app.constText.Tag = 'constText';
            app.constText.Value = '0.0';
            app.text4 = uilabel(app.GridLayout3);
            app.text4.Tag = 'text4';
            app.text4.Text = 'deg';
            app.constOptCheckbox = uicheckbox(app.GridLayout3);
            app.constOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @constOptCheckbox_Callback, true);
            app.constOptCheckbox.Tag = 'constOptCheckbox';
            app.constOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.constOptCheckbox.Text = 'Opt?';
            app.constOptCheckbox.Value = true;
            app.constLbText = uieditfield(app.GridLayout3, 'text');
            app.constLbText.ValueChangedFcn = createCallbackFcn(app, @constLbText_Callback, true);
            app.constLbText.Tag = 'constLbText';
            app.constLbText.Tooltip = 'Parameter lower bound.';
            app.constLbText.Value = '0.0';
            app.constUbText = uieditfield(app.GridLayout3, 'text');
            app.constUbText.ValueChangedFcn = createCallbackFcn(app, @constUbText_Callback, true);
            app.constUbText.Tag = 'constUbText';
            app.constUbText.Tooltip = 'Parameter upper bound.';
            app.constUbText.Value = '0.0';
            app.uipanel3 = uipanel(app.GridLayout2);
            app.uipanel3.Title = 'Terms';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout4 = uigridlayout(app.uipanel3);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.addTermButton = uibutton(app.GridLayout6, 'push');
            app.addTermButton.ButtonPushedFcn = createCallbackFcn(app, @addTermButton_Callback, true);
            app.addTermButton.Tag = 'addTermButton';
            app.addTermButton.Icon = 'add.png';
            app.addTermButton.Text = 'Add Term';
            app.removeTermButton = uibutton(app.GridLayout6, 'push');
            app.removeTermButton.ButtonPushedFcn = createCallbackFcn(app, @removeTermButton_Callback, true);
            app.removeTermButton.Tag = 'removeTermButton';
            app.removeTermButton.Icon = 'remove.png';
            app.removeTermButton.Text = 'Remove Term';
            app.termListbox = uilistbox(app.GridLayout5);
            app.termListbox.Items = {'Listbox'};
            app.termListbox.ValueChangedFcn = createCallbackFcn(app, @termListbox_Callback, true);
            app.termListbox.Tag = 'termListbox';
            app.termListbox.Value = 'Listbox';
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.plotPolynomialButton = uibutton(app.GridLayout8, 'push');
            app.plotPolynomialButton.ButtonPushedFcn = createCallbackFcn(app, @plotPolynomialButton_Callback, true);
            app.plotPolynomialButton.Tag = 'plotPolynomialButton';
            app.plotPolynomialButton.Icon = 'line-chart-data-label.png';
            app.plotPolynomialButton.Text = 'Plot Polynomial';
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.text13 = uilabel(app.GridLayout9);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Duration to Plot';
            app.durationToPlot = uieditfield(app.GridLayout9, 'text');
            app.durationToPlot.ValueChangedFcn = createCallbackFcn(app, @durationToPlot_Callback, true);
            app.durationToPlot.Tag = 'durationToPlot';
            app.durationToPlot.Value = '1.0';
            app.text14 = uilabel(app.GridLayout9);
            app.text14.Tag = 'text14';
            app.text14.Text = 'sec';
            app.uipanel4 = uipanel(app.GridLayout7);
            app.uipanel4.Title = 'Term Definition';
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout10 = uigridlayout(app.uipanel4);
            app.text5 = uilabel(app.GridLayout10);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Coefficient';
            app.text7 = uilabel(app.GridLayout10);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Exponent';
            app.coeffText = uieditfield(app.GridLayout10, 'text');
            app.coeffText.ValueChangedFcn = createCallbackFcn(app, @coeffText_Callback, true);
            app.coeffText.Tag = 'coeffText';
            app.coeffText.Value = '0.0';
            app.expText = uieditfield(app.GridLayout10, 'text');
            app.expText.ValueChangedFcn = createCallbackFcn(app, @expText_Callback, true);
            app.expText.Tag = 'expText';
            app.expText.Value = '0.0';
            app.text6 = uilabel(app.GridLayout10);
            app.text6.Tag = 'text6';
            app.text6.Tooltip = 'Units are actually deg / sec^Exponent';
            app.text6.Text = 'deg';
            app.text8 = uilabel(app.GridLayout10);
            app.text8.Tag = 'text8';
            app.text8.Text = '';
            app.coeffOptCheckbox = uicheckbox(app.GridLayout10);
            app.coeffOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @coeffOptCheckbox_Callback, true);
            app.coeffOptCheckbox.Tag = 'coeffOptCheckbox';
            app.coeffOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.coeffOptCheckbox.Text = 'Opt?';
            app.coeffOptCheckbox.Value = true;
            app.expOptCheckbox = uicheckbox(app.GridLayout10);
            app.expOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @expOptCheckbox_Callback, true);
            app.expOptCheckbox.Tag = 'expOptCheckbox';
            app.expOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.expOptCheckbox.Text = 'Opt?';
            app.expOptCheckbox.Value = true;
            app.coeffLbText = uieditfield(app.GridLayout10, 'text');
            app.coeffLbText.ValueChangedFcn = createCallbackFcn(app, @coeffLbText_Callback, true);
            app.coeffLbText.Tag = 'coeffLbText';
            app.coeffLbText.Tooltip = 'Parameter lower bound.';
            app.coeffLbText.Value = '0.0';
            app.expLbText = uieditfield(app.GridLayout10, 'text');
            app.expLbText.ValueChangedFcn = createCallbackFcn(app, @expLbText_Callback, true);
            app.expLbText.Tag = 'expLbText';
            app.expLbText.Tooltip = 'Parameter lower bound.';
            app.expLbText.Value = '0.0';
            app.coeffUbText = uieditfield(app.GridLayout10, 'text');
            app.coeffUbText.ValueChangedFcn = createCallbackFcn(app, @coeffUbText_Callback, true);
            app.coeffUbText.Tag = 'coeffUbText';
            app.coeffUbText.Tooltip = 'Parameter upper bound.';
            app.coeffUbText.Value = '0.0';
            app.expUbText = uieditfield(app.GridLayout10, 'text');
            app.expUbText.ValueChangedFcn = createCallbackFcn(app, @expUbText_Callback, true);
            app.expUbText.Tag = 'expUbText';
            app.expUbText.Tooltip = 'Parameter upper bound.';
            app.expUbText.Value = '0.0';
            app.termListboxMenu = uicontextmenu(app.lvd_EditSumOfPolynomialTermsModelGUI);
            app.termListboxMenu.Tag = 'termListboxMenu';
            app.copyTermMenu = uimenu(app.termListboxMenu);
            app.copyTermMenu.MenuSelectedFcn = createCallbackFcn(app, @copyTermMenu_Callback, true);
            app.copyTermMenu.Text = 'Copy Term';
            app.copyTermMenu.Tag = 'copyTermMenu';
            % Assign app.termListboxMenu
            app.termListbox.ContextMenu = app.termListboxMenu;
            % Show the figure after all components are created
            app.lvd_EditSumOfPolynomialTermsModelGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditSumOfPolynomialTermsModelGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditSumOfPolynomialTermsModelGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditSumOfPolynomialTermsModelGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditSumOfPolynomialTermsModelGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditSumOfPolynomialTermsModelGUI)
        end
    end
end