classdef lvd_EditSumOfSinesModelGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditSumOfSinesModelGUI  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        uipanel1                    matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        uipanel3                    matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        GridLayout7                 matlab.ui.container.GridLayout
        uipanel4                    matlab.ui.container.Panel
        GridLayout8                 matlab.ui.container.GridLayout
        phaseUbText                 matlab.ui.control.EditField
        periodUbText                matlab.ui.control.EditField
        ampUbText                   matlab.ui.control.EditField
        phaseLbText                 matlab.ui.control.EditField
        periodLbText                matlab.ui.control.EditField
        ampLbText                   matlab.ui.control.EditField
        phaseOptCheckbox            matlab.ui.control.CheckBox
        periodOptCheckbox           matlab.ui.control.CheckBox
        ampOptCheckbox              matlab.ui.control.CheckBox
        text10                      matlab.ui.control.Label
        text8                       matlab.ui.control.Label
        text6                       matlab.ui.control.Label
        phaseText                   matlab.ui.control.EditField
        periodText                  matlab.ui.control.EditField
        ampText                     matlab.ui.control.EditField
        text9                       matlab.ui.control.Label
        text7                       matlab.ui.control.Label
        text5                       matlab.ui.control.Label
        plotOnePeriodButton         matlab.ui.control.Button
        GridLayout5                 matlab.ui.container.GridLayout
        GridLayout6                 matlab.ui.container.GridLayout
        removeSineButton            matlab.ui.control.Button
        addSineButton               matlab.ui.control.Button
        sineListbox                 matlab.ui.control.ListBox
        uipanel2                    matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        constUbText                 matlab.ui.control.EditField
        constLbText                 matlab.ui.control.EditField
        constOptCheckbox            matlab.ui.control.CheckBox
        text4                       matlab.ui.control.Label
        constText                   matlab.ui.control.EditField
        text3                       matlab.ui.control.Label
        text12                      matlab.ui.control.Label
        text11                      matlab.ui.control.Label
        saveAndCloseButton          matlab.ui.control.Button
        text2                       matlab.ui.control.Label
        sineListboxMenu             matlab.ui.container.ContextMenu
        copySineWaveMenu            matlab.ui.container.Menu
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function sineModel = getSelectedSineModel(app, handles, sumOfSines)
            sineModel = app.sineListbox.Value;
            
%             numSines = sumOfSines.getNumSines();
% 
%             if(numSines > 0)
%                 selSineInd = handles.sineListbox.Value;
%                 if(numel(selSineInd) > 1)
%                     selSineInd = selSineInd(end);
%                 end
%                 
%                 sineModel = sumOfSines.getSineAtInd(selSineInd);
%             else
%                 sineModel = SineModel.empty(1,0);
%             end
        end

        function populateGUI(app, handles, sumOfSines)
            handles.constText.String = fullAccNum2Str(rad2deg(sumOfSines.const));
            handles.constOptCheckbox.Value = double(sumOfSines.varConst);
            handles.constLbText.String = fullAccNum2Str(rad2deg(sumOfSines.constLb));
            handles.constUbText.String = fullAccNum2Str(rad2deg(sumOfSines.constUb));

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            constOptCheckbox_Callback(app, evt);

            [sineListboxStr, sines] = sumOfSines.getListboxStr();
            app.sineListbox.Items = sineListboxStr;
            app.sineListbox.ItemsData = sines;
            
            if(~isempty(sineListboxStr))
                app.sineListbox.Value = sines(1);
            end

            populateSineDefinition(app, handles, sumOfSines);
        end

        function populateSineDefinition(app, handles, sumOfSines)
            sineModel = getSelectedSineModel(app, handles, sumOfSines);

            if(not(isempty(sineModel)))
                setSineModelWidgetsEnable(app, handles, 'on');

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);

                handles.ampText.String = fullAccNum2Str(rad2deg(sineModel.amp));
                handles.ampOptCheckbox.Value = double(sineModel.varAmp);
                handles.ampLbText.String = fullAccNum2Str(rad2deg(sineModel.ampLb));
                handles.ampUbText.String = fullAccNum2Str(rad2deg(sineModel.ampUb));
                ampOptCheckbox_Callback(app, evt);

                handles.periodText.String = fullAccNum2Str(sineModel.period);
                handles.periodOptCheckbox.Value = double(sineModel.varPeriod);
                handles.periodLbText.String = fullAccNum2Str(sineModel.periodLb);
                handles.periodUbText.String = fullAccNum2Str(sineModel.periodUb);
                periodOptCheckbox_Callback(app, evt);

                handles.phaseText.String = fullAccNum2Str(sineModel.phase);
                handles.phaseOptCheckbox.Value = double(sineModel.varPhase);
                handles.phaseLbText.String = fullAccNum2Str(sineModel.phaseLb);
                handles.phaseUbText.String = fullAccNum2Str(sineModel.phaseUb);
                phaseOptCheckbox_Callback(app, evt);

            else
                setSineModelWidgetsEnable(app, handles, 'off');
            end
        end

        function setSineModelWidgetsEnable(app, handles, enableStr)
            handles.ampText.Enable = enableStr;
            handles.ampOptCheckbox.Enable = enableStr;
            handles.ampLbText.Enable = enableStr;
            handles.ampUbText.Enable = enableStr;

            handles.periodText.Enable = enableStr;
            handles.periodOptCheckbox.Enable = enableStr;
            handles.periodLbText.Enable = enableStr;
            handles.periodUbText.Enable = enableStr;

            handles.phaseText.Enable = enableStr;
            handles.phaseOptCheckbox.Enable = enableStr;
            handles.phaseLbText.Enable = enableStr;
            handles.phaseUbText.Enable = enableStr;
        end

        function saveAndClose(app, handles)
            app.output.output = {true};
            close(handles.lvd_EditSumOfSinesModelGUI);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditSumOfSinesModelGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditSumOfSinesModelGUI (see VARARGIN)

            % Choose default command line output for lvd_EditSumOfSinesModelGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            sumOfSines = varargin{1};
            setappdata(hObject,'sumOfSines',sumOfSines);
            
            app.output = varargin{2};

            populateGUI(app, handles, sumOfSines);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditSumOfSinesModelGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditSumOfSinesModelGUI);
        end

        % Button pushed function: addSineButton
        function addSineButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addSineButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            sumOfSines = getappdata(handles.lvd_EditSumOfSinesModelGUI,'sumOfSines');

            sineModel = SineModel(0, 1, 2*pi, 0);
            sumOfSines.addSine(sineModel);

            [listBoxStr, sines] = sumOfSines.getListboxStr();
            app.sineListbox.Items = listBoxStr;
            app.sineListbox.ItemsData = sines;
            app.sineListbox.Value = sines(end);

            populateSineDefinition(app, handles, sumOfSines);
        end

        % Value changed function: ampLbText
        function ampLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to ampLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of ampLbText as text
            %        str2double(get(hObject,'String')) returns contents of ampLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            sumOfSines = getappdata(handles.lvd_EditSumOfSinesModelGUI,'sumOfSines');
            sineModel = getSelectedSineModel(app, handles, sumOfSines);

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Sine Amplitude Lower Bound';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                sineModel.ampLb = deg2rad(value);
            else
                hObject.String = fullAccNum2Str(sineModel.ampLb);

                msgbox(errMsg,'Invalid Sine Amplitude Lower Bound','error');
            end
        end

        % Value changed function: ampOptCheckbox
        function ampOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.ampOptCheckbox;

            % hObject    handle to ampOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of ampOptCheckbox
            sumOfSines = getappdata(handles.lvd_EditSumOfSinesModelGUI,'sumOfSines');
            sineModel = getSelectedSineModel(app, handles, sumOfSines);
            sineModel.varAmp = logical(hObject.Value);

            if(hObject.Value)
                handles.ampLbText.Enable = 'on';
                handles.ampUbText.Enable = 'on';
            else
                handles.ampLbText.Enable = 'off';
                handles.ampUbText.Enable = 'off';
            end
        end

        % Value changed function: ampText
        function ampText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to ampText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of ampText as text
            %        str2double(get(hObject,'String')) returns contents of ampText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            sumOfSines = getappdata(handles.lvd_EditSumOfSinesModelGUI,'sumOfSines');
            sineModel = getSelectedSineModel(app, handles, sumOfSines);

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Sine Amplitude';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                sineModel.amp = deg2rad(value);

                [listBoxStr, sines] = sumOfSines.getListboxStr();
                app.sineListbox.Items = listBoxStr;
                app.sineListbox.ItemsData = sines;
            else
                hObject.String = fullAccNum2Str(sineModel.amp);

                msgbox(errMsg,'Invalid Sine Amplitude','error');
            end
        end

        % Value changed function: ampUbText
        function ampUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to ampUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of ampUbText as text
            %        str2double(get(hObject,'String')) returns contents of ampUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            sumOfSines = getappdata(handles.lvd_EditSumOfSinesModelGUI,'sumOfSines');
            sineModel = getSelectedSineModel(app, handles, sumOfSines);

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Sine Amplitude Upper Bound';
            lb = sineModel.ampLb;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                sineModel.ampUb = deg2rad(value);
            else
                hObject.String = fullAccNum2Str(sineModel.ampUb);

                msgbox(errMsg,'Invalid Sine Amplitude Upper Bound','error');
            end
        end

        % Value changed function: constLbText
        function constLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to constLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of constLbText as text
            %        str2double(get(hObject,'String')) returns contents of constLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            sumOfSines = getappdata(handles.lvd_EditSumOfSinesModelGUI,'sumOfSines');

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Sine Constant Offset Lower Bound';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                sumOfSines.constLb = deg2rad(value);
            else
                hObject.String = fullAccNum2Str(sumOfSines.constLb);

                msgbox(errMsg,'Invalid Sine Constant Offset Lower Bound','error');
            end
        end

        % Value changed function: constOptCheckbox
        function constOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.constOptCheckbox;

            % hObject    handle to constOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of constOptCheckbox
            sumOfSines = getappdata(handles.lvd_EditSumOfSinesModelGUI,'sumOfSines');
            sumOfSines.varConst = logical(hObject.Value);

            if(hObject.Value)
                handles.constLbText.Enable = 'on';
                handles.constUbText.Enable = 'on';
            else
                handles.constLbText.Enable = 'off';
                handles.constUbText.Enable = 'off';
            end
        end

        % Value changed function: constText
        function constText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to constText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of constText as text
            %        str2double(get(hObject,'String')) returns contents of constText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            sumOfSines = getappdata(handles.lvd_EditSumOfSinesModelGUI,'sumOfSines');

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Sine Constant Offset';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                sumOfSines.const = deg2rad(value);
            else
                hObject.String = fullAccNum2Str(sumOfSines.const);

                msgbox(errMsg,'Invalid Sine Constant Offset','error');
            end
        end

        % Value changed function: constUbText
        function constUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to constUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of constUbText as text
            %        str2double(get(hObject,'String')) returns contents of constUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            sumOfSines = getappdata(handles.lvd_EditSumOfSinesModelGUI,'sumOfSines');

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Sine Constant Offset Upper Bound';
            lb = sumOfSines.constLb;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                sumOfSines.constUb = deg2rad(value);
            else
                hObject.String = fullAccNum2Str(sumOfSines.constUb);

                msgbox(errMsg,'Invalid Sine Constant Offset Upper Bound','error');
            end
        end

        % Menu selected function: copySineWaveMenu
        function copySineWaveMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to copySineWaveMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            sumOfSines = getappdata(handles.lvd_EditSumOfSinesModelGUI,'sumOfSines');
            sineModel = getSelectedSineModel(app, handles, sumOfSines);

            newSineModel = sineModel.deepCopy();
            sumOfSines.addSine(newSineModel);

            [listBoxStr, sines] = sumOfSines.getListboxStr();
            app.sineListbox.Items = listBoxStr;
            app.sineListbox.ItemsData = sines;
            app.sineListbox.Value = sines(end);

            populateSineDefinition(app, handles, sumOfSines);
        end

        % Value changed function: periodLbText
        function periodLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to periodLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of periodLbText as text
            %        str2double(get(hObject,'String')) returns contents of periodLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            sumOfSines = getappdata(handles.lvd_EditSumOfSinesModelGUI,'sumOfSines');
            sineModel = getSelectedSineModel(app, handles, sumOfSines);

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Sine Period Lower Bound';
            lb = 1E-10;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                sineModel.periodLb = value;
            else
                hObject.String = fullAccNum2Str(sineModel.periodLb);

                msgbox(errMsg,'Invalid Sine Period Lower Bound','error');
            end
        end

        % Value changed function: periodOptCheckbox
        function periodOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.periodOptCheckbox;

            % hObject    handle to periodOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of periodOptCheckbox
            sumOfSines = getappdata(handles.lvd_EditSumOfSinesModelGUI,'sumOfSines');
            sineModel = getSelectedSineModel(app, handles, sumOfSines);
            sineModel.varPeriod = logical(hObject.Value);

            if(hObject.Value)
                handles.periodLbText.Enable = 'on';
                handles.periodUbText.Enable = 'on';
            else
                handles.periodLbText.Enable = 'off';
                handles.periodUbText.Enable = 'off';
            end
        end

        % Value changed function: periodText
        function periodText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to periodText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of periodText as text
            %        str2double(get(hObject,'String')) returns contents of periodText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            sumOfSines = getappdata(handles.lvd_EditSumOfSinesModelGUI,'sumOfSines');
            sineModel = getSelectedSineModel(app, handles, sumOfSines);

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Sine Period';
            lb = 1E-10;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                sineModel.period = value;

                [listBoxStr, sines] = sumOfSines.getListboxStr();
                app.sineListbox.Items = listBoxStr;
                app.sineListbox.ItemsData = sines;
            else
                hObject.String = fullAccNum2Str(sineModel.period);

                msgbox(errMsg,'Invalid Sine Period','error');
            end
        end

        % Value changed function: periodUbText
        function periodUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to periodUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of periodUbText as text
            %        str2double(get(hObject,'String')) returns contents of periodUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            sumOfSines = getappdata(handles.lvd_EditSumOfSinesModelGUI,'sumOfSines');
            sineModel = getSelectedSineModel(app, handles, sumOfSines);

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Sine Period Upper Bound';
            lb = sineModel.periodLb;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                sineModel.periodUb = value;
            else
                hObject.String = fullAccNum2Str(sineModel.periodUb);

                msgbox(errMsg,'Invalid Sine Period Upper Bound','error');
            end
        end

        % Value changed function: phaseLbText
        function phaseLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to phaseLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of phaseLbText as text
            %        str2double(get(hObject,'String')) returns contents of phaseLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            sumOfSines = getappdata(handles.lvd_EditSumOfSinesModelGUI,'sumOfSines');
            sineModel = getSelectedSineModel(app, handles, sumOfSines);

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Sine Phase Shift Lower Bound';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                sineModel.phaseLb = value;
            else
                hObject.String = fullAccNum2Str(sineModel.phaseLb);

                msgbox(errMsg,'Invalid Sine Phase Shift Lower Bound','error');
            end
        end

        % Value changed function: phaseOptCheckbox
        function phaseOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.phaseOptCheckbox;

            % hObject    handle to phaseOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of phaseOptCheckbox
            sumOfSines = getappdata(handles.lvd_EditSumOfSinesModelGUI,'sumOfSines');
            sineModel = getSelectedSineModel(app, handles, sumOfSines);
            sineModel.varPhase = logical(hObject.Value);

            if(hObject.Value)
                handles.phaseLbText.Enable = 'on';
                handles.phaseUbText.Enable = 'on';
            else
                handles.phaseLbText.Enable = 'off';
                handles.phaseUbText.Enable = 'off';
            end
        end

        % Value changed function: phaseText
        function phaseText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to phaseText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of phaseText as text
            %        str2double(get(hObject,'String')) returns contents of phaseText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            sumOfSines = getappdata(handles.lvd_EditSumOfSinesModelGUI,'sumOfSines');
            sineModel = getSelectedSineModel(app, handles, sumOfSines);

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Sine Phase Shift';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                sineModel.phase = value;

                [listBoxStr, sines] = sumOfSines.getListboxStr();
                app.sineListbox.Items = listBoxStr;
                app.sineListbox.ItemsData = sines;
            else
                hObject.String = fullAccNum2Str(sineModel.phase);

                msgbox(errMsg,'Invalid Sine Phase Shift','error');
            end
        end

        % Value changed function: phaseUbText
        function phaseUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to phaseUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of phaseUbText as text
            %        str2double(get(hObject,'String')) returns contents of phaseUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            sumOfSines = getappdata(handles.lvd_EditSumOfSinesModelGUI,'sumOfSines');
            sineModel = getSelectedSineModel(app, handles, sumOfSines);

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Sine Phase Shift Upper Bound';
            lb = sineModel.phaseLb;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                sineModel.phaseUb = value;
            else
                hObject.String = fullAccNum2Str(sineModel.phaseUb);

                msgbox(errMsg,'Invalid Sine Phase Shift Upper Bound','error');
            end
        end

        % Button pushed function: plotOnePeriodButton
        function plotOnePeriodButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to plotOnePeriodButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            sumOfSines = getappdata(handles.lvd_EditSumOfSinesModelGUI,'sumOfSines');
            period = sumOfSines.getLongestPeriod();

            if(period == 0)
                period = 1;
            end

            t0 = sumOfSines.getT0();
            t = linspace(t0,t0+period,500);
            y = NaN(1,length(t));

            for(i=1:length(t)) %#ok<*NO4LP>
                y(i) = rad2deg(sumOfSines.getValueAtTime(t(i)));
            end

            hAx = axes(figure());
            plot(hAx, t, y);
            grid(hAx,'minor');
            xlabel('DT [sec]');
            ylabel('Sum of Sines Value [deg]');
        end

        % Button pushed function: removeSineButton
        function removeSineButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeSineButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            sumOfSines = getappdata(handles.lvd_EditSumOfSinesModelGUI,'sumOfSines');
            sineModel = getSelectedSineModel(app, handles, sumOfSines);

            if(not(isempty(sineModel)))
                sumOfSines.removeSine(sineModel);
            end

            [listBoxStr, sines] = sumOfSines.getListboxStr();
%             handles.sineListbox.String = listBoxStr;
            app.sineListbox.Items = listBoxStr;
            app.sineListbox.ItemsData = sines;

%             if(handles.sineListbox.Value > length(listBoxStr))
%                 handles.sineListbox.Value = length(listBoxStr);
%             end

            populateSineDefinition(app, handles, sumOfSines);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.saveAndClose(handles);
        end

        % Value changed function: sineListbox
        function sineListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to sineListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns sineListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from sineListbox
            sumOfSines = getappdata(handles.lvd_EditSumOfSinesModelGUI,'sumOfSines');
            populateSineDefinition(app, handles, sumOfSines);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditSumOfSinesModelGUI = uifigure('Visible', 'off');
            app.lvd_EditSumOfSinesModelGUI.Name = 'Edit Sum of Sines Model';
            app.lvd_EditSumOfSinesModelGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditSumOfSinesModelGUI.Tag = 'lvd_EditSumOfSinesModelGUI';
            app.GridLayout = uigridlayout(app.lvd_EditSumOfSinesModelGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Sum of Sines Model';
            app.saveAndCloseButton = uibutton(app.GridLayout, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout2 = uigridlayout(app.uipanel1);
            app.text11 = uilabel(app.GridLayout2);
            app.text11.Tag = 'text11';
            app.text11.Tooltip = 'Where dt is time since the start of this steering law plus the time offset, t = t0 + tOffset.';
            app.text11.Text = 'this_quantity = const + amp1 * sin(freq1 * (dt + phase_shift1)) + ... ';
            app.text12 = uilabel(app.GridLayout2);
            app.text12.Tag = 'text12';
            app.text12.Tooltip = 'Where dt is time since the start of this steering law plus the time offset, t = t0 + tOffset.';
            app.text12.Text = '                 ampN * sin(freqN * (dt + phase_shiftN))';
            app.uipanel2 = uipanel(app.GridLayout2);
            app.uipanel2.Title = 'Constant';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout3 = uigridlayout(app.uipanel2);
            app.text3 = uilabel(app.GridLayout3);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Constant Offset';
            app.constText = uieditfield(app.GridLayout3, 'text');
            app.constText.ValueChangedFcn = createCallbackFcn(app, @constText_Callback, true);
            app.constText.Tag = 'constText';
            app.constText.Value = '0.0';
            app.text4 = uilabel(app.GridLayout3);
            app.text4.Tag = 'text4';
            app.text4.Text = 'deg';
            app.constOptCheckbox = uicheckbox(app.GridLayout3);
            app.constOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @constOptCheckbox_Callback, true);
            app.constOptCheckbox.Tag = 'constOptCheckbox';
            app.constOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.constOptCheckbox.Text = 'Opt?';
            app.constOptCheckbox.Value = true;
            app.constLbText = uieditfield(app.GridLayout3, 'text');
            app.constLbText.ValueChangedFcn = createCallbackFcn(app, @constLbText_Callback, true);
            app.constLbText.Tag = 'constLbText';
            app.constLbText.Tooltip = 'Parameter lower bound.';
            app.constLbText.Value = '0.0';
            app.constUbText = uieditfield(app.GridLayout3, 'text');
            app.constUbText.ValueChangedFcn = createCallbackFcn(app, @constUbText_Callback, true);
            app.constUbText.Tag = 'constUbText';
            app.constUbText.Tooltip = 'Parameter upper bound.';
            app.constUbText.Value = '0.0';
            app.uipanel3 = uipanel(app.GridLayout2);
            app.uipanel3.Title = 'Sine Models';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout4 = uigridlayout(app.uipanel3);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.sineListbox = uilistbox(app.GridLayout5);
            app.sineListbox.Items = {'Listbox'};
            app.sineListbox.ValueChangedFcn = createCallbackFcn(app, @sineListbox_Callback, true);
            app.sineListbox.Tag = 'sineListbox';
            app.sineListbox.Value = 'Listbox';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.addSineButton = uibutton(app.GridLayout6, 'push');
            app.addSineButton.ButtonPushedFcn = createCallbackFcn(app, @addSineButton_Callback, true);
            app.addSineButton.Tag = 'addSineButton';
            app.addSineButton.Icon = 'add.png';
            app.addSineButton.Text = 'Add Sine';
            app.removeSineButton = uibutton(app.GridLayout6, 'push');
            app.removeSineButton.ButtonPushedFcn = createCallbackFcn(app, @removeSineButton_Callback, true);
            app.removeSineButton.Tag = 'removeSineButton';
            app.removeSineButton.Icon = 'remove.png';
            app.removeSineButton.Text = 'Remove Sine';
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.plotOnePeriodButton = uibutton(app.GridLayout7, 'push');
            app.plotOnePeriodButton.ButtonPushedFcn = createCallbackFcn(app, @plotOnePeriodButton_Callback, true);
            app.plotOnePeriodButton.Tag = 'plotOnePeriodButton';
            app.plotOnePeriodButton.Icon = 'line-chart-data-label.png';
            app.plotOnePeriodButton.Text = 'Plot One Period';
            app.uipanel4 = uipanel(app.GridLayout7);
            app.uipanel4.Title = 'Sine Definition';
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout8 = uigridlayout(app.uipanel4);
            app.text5 = uilabel(app.GridLayout8);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Amplitude';
            app.text7 = uilabel(app.GridLayout8);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Period';
            app.text9 = uilabel(app.GridLayout8);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Phase Shift';
            app.ampText = uieditfield(app.GridLayout8, 'text');
            app.ampText.ValueChangedFcn = createCallbackFcn(app, @ampText_Callback, true);
            app.ampText.Tag = 'ampText';
            app.ampText.Value = '0.0';
            app.periodText = uieditfield(app.GridLayout8, 'text');
            app.periodText.ValueChangedFcn = createCallbackFcn(app, @periodText_Callback, true);
            app.periodText.Tag = 'periodText';
            app.periodText.Value = '0.0';
            app.phaseText = uieditfield(app.GridLayout8, 'text');
            app.phaseText.ValueChangedFcn = createCallbackFcn(app, @phaseText_Callback, true);
            app.phaseText.Tag = 'phaseText';
            app.phaseText.Value = '0.0';
            app.text6 = uilabel(app.GridLayout8);
            app.text6.Tag = 'text6';
            app.text6.Text = 'deg';
            app.text8 = uilabel(app.GridLayout8);
            app.text8.Tag = 'text8';
            app.text8.Text = 'sec';
            app.text10 = uilabel(app.GridLayout8);
            app.text10.Tag = 'text10';
            app.text10.Text = 'sec';
            app.ampOptCheckbox = uicheckbox(app.GridLayout8);
            app.ampOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @ampOptCheckbox_Callback, true);
            app.ampOptCheckbox.Tag = 'ampOptCheckbox';
            app.ampOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.ampOptCheckbox.Text = 'Opt?';
            app.ampOptCheckbox.Value = true;
            app.periodOptCheckbox = uicheckbox(app.GridLayout8);
            app.periodOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @periodOptCheckbox_Callback, true);
            app.periodOptCheckbox.Tag = 'periodOptCheckbox';
            app.periodOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.periodOptCheckbox.Text = 'Opt?';
            app.periodOptCheckbox.Value = true;
            app.phaseOptCheckbox = uicheckbox(app.GridLayout8);
            app.phaseOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @phaseOptCheckbox_Callback, true);
            app.phaseOptCheckbox.Tag = 'phaseOptCheckbox';
            app.phaseOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.phaseOptCheckbox.Text = 'Opt?';
            app.phaseOptCheckbox.Value = true;
            app.ampLbText = uieditfield(app.GridLayout8, 'text');
            app.ampLbText.ValueChangedFcn = createCallbackFcn(app, @ampLbText_Callback, true);
            app.ampLbText.Tag = 'ampLbText';
            app.ampLbText.Tooltip = 'Parameter lower bound.';
            app.ampLbText.Value = '0.0';
            app.periodLbText = uieditfield(app.GridLayout8, 'text');
            app.periodLbText.ValueChangedFcn = createCallbackFcn(app, @periodLbText_Callback, true);
            app.periodLbText.Tag = 'periodLbText';
            app.periodLbText.Tooltip = 'Parameter lower bound.';
            app.periodLbText.Value = '0.0';
            app.phaseLbText = uieditfield(app.GridLayout8, 'text');
            app.phaseLbText.ValueChangedFcn = createCallbackFcn(app, @phaseLbText_Callback, true);
            app.phaseLbText.Tag = 'phaseLbText';
            app.phaseLbText.Tooltip = 'Parameter lower bound.';
            app.phaseLbText.Value = '0.0';
            app.ampUbText = uieditfield(app.GridLayout8, 'text');
            app.ampUbText.ValueChangedFcn = createCallbackFcn(app, @ampUbText_Callback, true);
            app.ampUbText.Tag = 'ampUbText';
            app.ampUbText.Tooltip = 'Parameter upper bound.';
            app.ampUbText.Value = '0.0';
            app.periodUbText = uieditfield(app.GridLayout8, 'text');
            app.periodUbText.ValueChangedFcn = createCallbackFcn(app, @periodUbText_Callback, true);
            app.periodUbText.Tag = 'periodUbText';
            app.periodUbText.Tooltip = 'Parameter upper bound.';
            app.periodUbText.Value = '0.0';
            app.phaseUbText = uieditfield(app.GridLayout8, 'text');
            app.phaseUbText.ValueChangedFcn = createCallbackFcn(app, @phaseUbText_Callback, true);
            app.phaseUbText.Tag = 'phaseUbText';
            app.phaseUbText.Tooltip = 'Parameter upper bound.';
            app.phaseUbText.Value = '0.0';
            app.sineListboxMenu = uicontextmenu(app.lvd_EditSumOfSinesModelGUI);
            app.sineListboxMenu.Tag = 'sineListboxMenu';
            app.copySineWaveMenu = uimenu(app.sineListboxMenu);
            app.copySineWaveMenu.MenuSelectedFcn = createCallbackFcn(app, @copySineWaveMenu_Callback, true);
            app.copySineWaveMenu.Text = 'Copy Sine Wave';
            app.copySineWaveMenu.Tag = 'copySineWaveMenu';
            % Assign app.sineListboxMenu
            app.sineListbox.ContextMenu = app.sineListboxMenu;
            % Show the figure after all components are created
            app.lvd_EditSumOfSinesModelGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditSumOfSinesModelGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditSumOfSinesModelGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditSumOfSinesModelGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditSumOfSinesModelGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditSumOfSinesModelGUI)
        end
    end
end