classdef lvd_EditActionSetThrottleModelGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditActionSetThrottleModelGUI  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        uipanel1                    matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        angle1Panel                 matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        GridLayout6                 matlab.ui.container.GridLayout
        throttleAccelUbText         matlab.ui.control.EditField
        throttleLinUbText           matlab.ui.control.EditField
        throttleConstUbText         matlab.ui.control.EditField
        throttleAccelLbText         matlab.ui.control.EditField
        throttleLinLbText           matlab.ui.control.EditField
        throttleConstLbText         matlab.ui.control.EditField
        throttleAccelOptCheckbox    matlab.ui.control.CheckBox
        throttleLinOptCheckbox      matlab.ui.control.CheckBox
        throttleConstOptCheckbox    matlab.ui.control.CheckBox
        text10                      matlab.ui.control.Label
        text8                       matlab.ui.control.Label
        text6                       matlab.ui.control.Label
        throttleAccelTermText       matlab.ui.control.EditField
        throttleLinTermText         matlab.ui.control.EditField
        throttleConstTermText       matlab.ui.control.EditField
        text9                       matlab.ui.control.Label
        text7                       matlab.ui.control.Label
        text5                       matlab.ui.control.Label
        text24                      matlab.ui.control.Label
        uipanel6                    matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        timeOffsetConstUbText       matlab.ui.control.EditField
        timeOffsetConstLbText       matlab.ui.control.EditField
        timeOffsetConstOptCheckbox  matlab.ui.control.CheckBox
        text28                      matlab.ui.control.Label
        timeOffsetText              matlab.ui.control.EditField
        text27                      matlab.ui.control.Label
        throttleContCheckbox        matlab.ui.control.CheckBox
        GridLayout2                 matlab.ui.container.GridLayout
        cancelButton                matlab.ui.control.Button
        saveAndCloseButton          matlab.ui.control.Button
        titleLabel                  matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, throttleModel, useContinuity)
            polyModel = throttleModel.throttleModel;
            handles.timeOffsetText.String = fullAccNum2Str(polyModel.tOffset);
            set(handles.throttleConstTermText,'String',fullAccNum2Str(100*polyModel.constTerm));
            set(handles.throttleLinTermText,'String',fullAccNum2Str(100*polyModel.linearTerm));
            set(handles.throttleAccelTermText,'String',fullAccNum2Str(100*polyModel.accelTerm));

            optVar = throttleModel.getExistingOptVar();
            if(isempty(optVar))
                useTf = false([1,4]);
                lb = zeros(size(useTf));
                ub = zeros(size(useTf));
            else
                useTf = optVar.getUseTfForVariable();
                optVar.setUseTfForVariable(true(size(useTf)));
                [lb, ub] = optVar.getBndsForVariable();
                optVar.setUseTfForVariable(useTf);
            end

            set(handles.throttleConstOptCheckbox,'Value',(useTf(1)));
            set(handles.throttleLinOptCheckbox,'Value',(useTf(2)));
            set(handles.throttleAccelOptCheckbox,'Value',(useTf(3)));
            set(handles.timeOffsetConstOptCheckbox,'Value',(useTf(4)));

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            throttleConstOptCheckbox_Callback(app, evt);
            throttleLinOptCheckbox_Callback(app, evt);
            throttleAccelOptCheckbox_Callback(app, evt);
            timeOffsetConstOptCheckbox_Callback(app, evt);

            %LB
            set(handles.throttleConstLbText,'String',fullAccNum2Str(100*lb(1)));
            set(handles.throttleLinLbText,'String',fullAccNum2Str(100*lb(2)));
            set(handles.throttleAccelLbText,'String',fullAccNum2Str(100*lb(3)));
            set(handles.timeOffsetConstLbText,'String',fullAccNum2Str(lb(4)));

            %UB
            set(handles.throttleConstUbText,'String',fullAccNum2Str(100*ub(1)));
            set(handles.throttleLinUbText,'String',fullAccNum2Str(100*ub(2)));
            set(handles.throttleAccelUbText,'String',fullAccNum2Str(100*ub(3)));
            set(handles.timeOffsetConstUbText,'String',fullAccNum2Str(ub(4)));

            if(useContinuity)
                handles.throttleContCheckbox.Enable = 'on';
            else
                handles.throttleContCheckbox.Enable = 'off';
            end
            handles.throttleContCheckbox.Value = double(throttleModel.throttleContinuity);
            
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            throttleContCheckbox_Callback(app, evt);
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            %Throttle
            throttleConst = str2double(get(handles.throttleConstTermText,'String'));
            enteredStr = get(handles.throttleConstTermText,'String');
            numberName = sprintf('Throttle Constant Term');
            lb = 0;
            ub = 100;
            isInt = false;
            errMsg = validateNumber(throttleConst, numberName, lb, ub, isInt, errMsg, enteredStr);

            throttleLinear = str2double(get(handles.throttleLinTermText,'String'));
            enteredStr = get(handles.throttleLinTermText,'String');
            numberName = sprintf('Throttle Linear Term');
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(throttleLinear, numberName, lb, ub, isInt, errMsg, enteredStr);

            throttleAccel = str2double(get(handles.throttleAccelTermText,'String'));
            enteredStr = get(handles.throttleAccelTermText,'String');
            numberName = sprintf('Throttle Acceleration Term');
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(throttleAccel, numberName, lb, ub, isInt, errMsg, enteredStr);

            %Time offset
            timeOffset = str2double(get(handles.timeOffsetText,'String'));
            enteredStr = get(handles.timeOffsetText,'String');
            numberName = 'Time Offset';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(timeOffset, numberName, lb, ub, isInt, errMsg, enteredStr);

            %%%%%Bounds
            %Throttle Const
            if(handles.throttleConstOptCheckbox.Value == 1)
                throttleConstLB = str2double(get(handles.throttleConstLbText,'String'));
                enteredStr = get(handles.throttleConstLbText,'String');
                numberName = sprintf('Throttle Constant Term Lower Bound');
                lb = 0;
                ub = 100;
                isInt = false;
                errMsg = validateNumber(throttleConstLB, numberName, lb, ub, isInt, errMsg, enteredStr);

                throttleConstUB = str2double(get(handles.throttleConstUbText,'String'));
                enteredStr = get(handles.throttleConstUbText,'String');
                numberName = sprintf('Throttle Constant Term Upper Bound');
                lb = throttleConstLB;
                ub = 100;
                isInt = false;
                errMsg = validateNumber(throttleConstUB, numberName, lb, ub, isInt, errMsg, enteredStr);
            end

            %Throttle Linear
            if(handles.throttleLinOptCheckbox.Value == 1)
                throttleLinearLB = str2double(get(handles.throttleLinLbText,'String'));
                enteredStr = get(handles.throttleLinLbText,'String');
                numberName = sprintf('Throttle Linear Term Lower Bound');
                lb = -Inf;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(throttleLinearLB, numberName, lb, ub, isInt, errMsg, enteredStr);

                throttleLinearUB = str2double(get(handles.throttleLinUbText,'String'));
                enteredStr = get(handles.throttleLinUbText,'String');
                numberName = sprintf('Throttle Linear Term Upper Bound');
                lb = throttleLinearLB;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(throttleLinearUB, numberName, lb, ub, isInt, errMsg, enteredStr);
            end

            %Throttle Accel
            if(handles.throttleAccelOptCheckbox.Value == 1)
                throttleAccelLB = str2double(get(handles.throttleAccelLbText,'String'));
                enteredStr = get(handles.throttleAccelLbText,'String');
                numberName = sprintf('Throttle Acceleration Term Lower Bound');
                lb = -Inf;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(throttleAccelLB, numberName, lb, ub, isInt, errMsg, enteredStr);

                throttleAccelUB = str2double(get(handles.throttleAccelUbText,'String'));
                enteredStr = get(handles.throttleAccelUbText,'String');
                numberName = sprintf('Throttle Acceleration Term Upper Bound');
                lb = throttleAccelLB;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(throttleAccelUB, numberName, lb, ub, isInt, errMsg, enteredStr);
            end

            %Time Offset
            if(handles.timeOffsetConstOptCheckbox.Value == 1)
                timeOffsetLb = str2double(get(handles.timeOffsetConstLbText,'String'));
                enteredStr = get(handles.timeOffsetConstLbText,'String');
                numberName = 'Time Offset Lower Bound';
                lb = -Inf;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(timeOffsetLb, numberName, lb, ub, isInt, errMsg, enteredStr);

                timeOffsetUb = str2double(get(handles.timeOffsetConstUbText,'String'));
                enteredStr = get(handles.timeOffsetConstUbText,'String');
                numberName = 'Time Offset Upper Bound';
                lb = timeOffsetLb;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(timeOffsetUb, numberName, lb, ub, isInt, errMsg, enteredStr);
            end

            if(isempty(errMsg))
                if(handles.throttleConstOptCheckbox.Value == 1 && (throttleConst < throttleConstLB || throttleConst > throttleConstUB))
                    errMsg{end+1} = 'Throttle constant term must be between the upper and lower optimization bounds.';
                end

                if(handles.throttleLinOptCheckbox.Value == 1 && (throttleLinear < throttleLinearLB || throttleLinear > throttleLinearUB))
                    errMsg{end+1} = 'Throttle linear term must be between the upper and lower optimization bounds.';
                end

                if(handles.throttleAccelOptCheckbox.Value == 1 && (throttleAccel < throttleAccelLB || throttleAccel > throttleAccelUB))
                    errMsg{end+1} = 'Throttle acceleration term must be between the upper and lower optimization bounds.';
                end

                if(handles.timeOffsetConstOptCheckbox.Value == 1 && (timeOffset < timeOffsetLb || timeOffset > timeOffsetUb))
                    errMsg{end+1} = 'Time offset must be between the upper and lower optimization bounds.';
                end
            end
        end


        function saveAndClose(app, handles)
            lv = getappdata(handles.lvd_EditActionSetThrottleModelGUI,'lv');
            lvdData = lv.lvdData;

            throttleModel = getappdata(handles.lvd_EditActionSetThrottleModelGUI,'throttleModel');

            optVar = throttleModel.getExistingOptVar();
            if(not(isempty(optVar))) %need to remove existing var if it exists
                lvdData.optimizer.vars.removeVariable(optVar);
            end

            %Set Throttle Terms
            timeOffset = str2double(get(handles.timeOffsetText,'String'));

            throttleConst = str2double(get(handles.throttleConstTermText,'String'))/100;
            throttleLinear = str2double(get(handles.throttleLinTermText,'String'))/100;
            throttleAccel = str2double(get(handles.throttleAccelTermText,'String'))/100;

            throttleModel.setTimeOffsets(timeOffset);
            throttleModel.setPolyTerms(throttleConst, throttleLinear, throttleAccel);
            throttleModel.throttleContinuity = logical(handles.throttleContCheckbox.Value);

            %Set Opt T/F
            useTf(1) = get(handles.throttleConstOptCheckbox,'Value');
            useTf(2) = get(handles.throttleLinOptCheckbox,'Value');
            useTf(3) = get(handles.throttleAccelOptCheckbox,'Value');
            useTf(4) = get(handles.timeOffsetConstOptCheckbox,'Value');

            if(isempty(optVar))
                optVar = throttleModel.getNewOptVar();
            end

            optVar.setUseTfForVariable(useTf);

            %LB
            lb(1) = str2double(get(handles.throttleConstLbText,'String'))/100;
            lb(2) = str2double(get(handles.throttleLinLbText,'String'))/100;
            lb(3) = str2double(get(handles.throttleAccelLbText,'String'))/100;
            lb(4) = str2double(get(handles.timeOffsetConstLbText,'String'));

            %UB
            ub(1) = str2double(get(handles.throttleConstUbText,'String'))/100;
            ub(2) = str2double(get(handles.throttleLinUbText,'String'))/100;
            ub(3) = str2double(get(handles.throttleAccelUbText,'String'))/100;
            ub(4) = str2double(get(handles.timeOffsetConstUbText,'String'));

            optVar.setUseTfForVariable(true(size(lb))); %need this to get the full lb/set in there
            optVar.setBndsForVariable(lb, ub);
            optVar.setUseTfForVariable(useTf);

            lvdData.optimizer.vars.addVariable(optVar);

            app.output.output = {true, throttleModel};
            close(handles.lvd_EditActionSetThrottleModelGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditActionSetThrottleModelGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditActionSetThrottleModelGUI (see VARARGIN)

            % Choose default command line output for lvd_EditActionSetThrottleModelGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            throttleModel = varargin{1};
            setappdata(hObject,'throttleModel',throttleModel);

            lv = varargin{2};
            setappdata(hObject,'lv',lv);

            useContinuity = varargin{3};
            setappdata(hObject,'useContinuity',useContinuity);
            
            app.output = varargin{4};

            populateGUI(app, handles, throttleModel, useContinuity);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditActionSetThrottleModelGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditActionSetThrottleModelGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            throttleModel = getappdata(handles.lvd_EditActionSetThrottleModelGUI,'throttleModel');
            app.output.output = {false, throttleModel};

            close(handles.lvd_EditActionSetThrottleModelGUI);
        end

        % Window key press function: lvd_EditActionSetThrottleModelGUI
        function lvd_EditActionSetThrottleModelGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditActionSetThrottleModelGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditActionSetThrottleModelGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                app.saveAndClose(handles);
            else
                uialert(app.lvd_EditActionSetThrottleModelGUI, errMsg, 'Errors were found while editing the throttle model.', "Icon","error");
            end
        end

        % Value changed function: throttleAccelLbText
        function throttleAccelLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to throttleAccelLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of throttleAccelLbText as text
            %        str2double(get(hObject,'String')) returns contents of throttleAccelLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: throttleAccelOptCheckbox
        function throttleAccelOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.throttleAccelOptCheckbox;
            
            % hObject    handle to throttleAccelOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of throttleAccelOptCheckbox
            if(get(hObject,'Value')==1)
                set(handles.throttleAccelLbText,'Enable','on');
                set(handles.throttleAccelUbText,'Enable','on');
            else
                set(handles.throttleAccelLbText,'Enable','off');
                set(handles.throttleAccelUbText,'Enable','off');
            end
        end

        % Value changed function: throttleAccelTermText
        function throttleAccelTermText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to throttleAccelTermText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of throttleAccelTermText as text
            %        str2double(get(hObject,'String')) returns contents of throttleAccelTermText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: throttleAccelUbText
        function throttleAccelUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to throttleAccelUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of throttleAccelUbText as text
            %        str2double(get(hObject,'String')) returns contents of throttleAccelUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: throttleConstLbText
        function throttleConstLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to throttleConstLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of throttleConstLbText as text
            %        str2double(get(hObject,'String')) returns contents of throttleConstLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: throttleConstOptCheckbox
        function throttleConstOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.throttleConstOptCheckbox;
            
            % hObject    handle to throttleConstOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of throttleConstOptCheckbox
            if(get(hObject,'Value')==1 && handles.throttleContCheckbox.Value == 0)
                set(handles.throttleConstLbText,'Enable','on');
                set(handles.throttleConstUbText,'Enable','on');
            else
                set(handles.throttleConstLbText,'Enable','off');
                set(handles.throttleConstUbText,'Enable','off');
            end
        end

        % Value changed function: throttleConstTermText
        function throttleConstTermText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to throttleConstTermText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of throttleConstTermText as text
            %        str2double(get(hObject,'String')) returns contents of throttleConstTermText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: throttleConstUbText
        function throttleConstUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to throttleConstUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of throttleConstUbText as text
            %        str2double(get(hObject,'String')) returns contents of throttleConstUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: throttleContCheckbox
        function throttleContCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.throttleContCheckbox;
            
            % hObject    handle to throttleContCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of throttleContCheckbox
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            if(hObject.Value)
                %throttle values
                handles.throttleConstTermText.Enable = 'off';
                handles.throttleConstOptCheckbox.Enable = 'off';
                handles.throttleConstOptCheckbox.Value = 0;

                throttleConstOptCheckbox_Callback(app, evt);

                %time offset
                handles.timeOffsetText.Enable = 'off';
                handles.timeOffsetConstOptCheckbox.Enable = 'off';
                handles.timeOffsetConstOptCheckbox.Value = 0;

                timeOffsetConstOptCheckbox_Callback(app, evt);
            else
                %throttle values
                handles.throttleConstTermText.Enable = 'on';
                handles.throttleConstOptCheckbox.Enable = 'on';

                throttleConstOptCheckbox_Callback(app, evt);

                %time offset
                handles.timeOffsetText.Enable = 'on';
                handles.timeOffsetConstOptCheckbox.Enable = 'on';

                timeOffsetConstOptCheckbox_Callback(app, evt);
            end
        end

        % Value changed function: throttleLinLbText
        function throttleLinLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to throttleLinLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of throttleLinLbText as text
            %        str2double(get(hObject,'String')) returns contents of throttleLinLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: throttleLinOptCheckbox
        function throttleLinOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.throttleLinOptCheckbox;
            
            % hObject    handle to throttleLinOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of throttleLinOptCheckbox
            if(get(hObject,'Value')==1)
                set(handles.throttleLinLbText,'Enable','on');
                set(handles.throttleLinUbText,'Enable','on');
            else
                set(handles.throttleLinLbText,'Enable','off');
                set(handles.throttleLinUbText,'Enable','off');
            end
        end

        % Value changed function: throttleLinTermText
        function throttleLinTermText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to throttleLinTermText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of throttleLinTermText as text
            %        str2double(get(hObject,'String')) returns contents of throttleLinTermText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: throttleLinUbText
        function throttleLinUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to throttleLinUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of throttleLinUbText as text
            %        str2double(get(hObject,'String')) returns contents of throttleLinUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: timeOffsetConstLbText
        function timeOffsetConstLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to timeOffsetConstLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of timeOffsetConstLbText as text
            %        str2double(get(hObject,'String')) returns contents of timeOffsetConstLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: timeOffsetConstOptCheckbox
        function timeOffsetConstOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.timeOffsetConstOptCheckbox;
            
            % hObject    handle to timeOffsetConstOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of timeOffsetConstOptCheckbox
            if(get(hObject,'Value')==1)
                set(handles.timeOffsetConstLbText,'Enable','on');
                set(handles.timeOffsetConstUbText,'Enable','on');
            else
                set(handles.timeOffsetConstLbText,'Enable','off');
                set(handles.timeOffsetConstUbText,'Enable','off');
            end
        end

        % Value changed function: timeOffsetConstUbText
        function timeOffsetConstUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to timeOffsetConstUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of timeOffsetConstUbText as text
            %        str2double(get(hObject,'String')) returns contents of timeOffsetConstUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: timeOffsetText
        function timeOffsetText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to timeOffsetText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of timeOffsetText as text
            %        str2double(get(hObject,'String')) returns contents of timeOffsetText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditActionSetThrottleModelGUI = uifigure('Visible', 'off');
            app.lvd_EditActionSetThrottleModelGUI.Name = 'Edit Throttle Model';
            app.lvd_EditActionSetThrottleModelGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditActionSetThrottleModelGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditActionSetThrottleModelGUI_WindowKeyPressFcn, true);
            app.lvd_EditActionSetThrottleModelGUI.Tag = 'lvd_EditActionSetThrottleModelGUI';
            app.GridLayout = uigridlayout(app.lvd_EditActionSetThrottleModelGUI);
            app.titleLabel = uilabel(app.GridLayout);
            app.titleLabel.Tag = 'titleLabel';
            app.titleLabel.Text = 'Edit Throttle Model';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.throttleContCheckbox = uicheckbox(app.GridLayout3);
            app.throttleContCheckbox.ValueChangedFcn = createCallbackFcn(app, @throttleContCheckbox_Callback, true);
            app.throttleContCheckbox.Tag = 'throttleContCheckbox';
            app.throttleContCheckbox.Tooltip = 'When checked, the constant term of the polynomial is replaced with the throttle at the point this action is evaluated.';
            app.throttleContCheckbox.Text = 'Preserve throttle continuity at the start of this Throttle Model?';
            app.uipanel6 = uipanel(app.GridLayout3);
            app.uipanel6.Title = 'Time Offset';
            app.uipanel6.Tag = 'uipanel6';
            app.GridLayout4 = uigridlayout(app.uipanel6);
            app.text27 = uilabel(app.GridLayout4);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Time Offset';
            app.timeOffsetText = uieditfield(app.GridLayout4, 'text');
            app.timeOffsetText.ValueChangedFcn = createCallbackFcn(app, @timeOffsetText_Callback, true);
            app.timeOffsetText.Tag = 'timeOffsetText';
            app.timeOffsetText.Tooltip = 'An offset to the time, t, that is used to compute the angle values.';
            app.timeOffsetText.Value = '0.0';
            app.text28 = uilabel(app.GridLayout4);
            app.text28.Tag = 'text28';
            app.text28.Text = 'sec';
            app.timeOffsetConstOptCheckbox = uicheckbox(app.GridLayout4);
            app.timeOffsetConstOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @timeOffsetConstOptCheckbox_Callback, true);
            app.timeOffsetConstOptCheckbox.Tag = 'timeOffsetConstOptCheckbox';
            app.timeOffsetConstOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.timeOffsetConstOptCheckbox.Text = 'Opt?';
            app.timeOffsetConstOptCheckbox.Value = true;
            app.timeOffsetConstLbText = uieditfield(app.GridLayout4, 'text');
            app.timeOffsetConstLbText.ValueChangedFcn = createCallbackFcn(app, @timeOffsetConstLbText_Callback, true);
            app.timeOffsetConstLbText.Tag = 'timeOffsetConstLbText';
            app.timeOffsetConstLbText.Tooltip = 'Parameter lower bound.';
            app.timeOffsetConstLbText.Value = '0.0';
            app.timeOffsetConstUbText = uieditfield(app.GridLayout4, 'text');
            app.timeOffsetConstUbText.ValueChangedFcn = createCallbackFcn(app, @timeOffsetConstUbText_Callback, true);
            app.timeOffsetConstUbText.Tag = 'timeOffsetConstUbText';
            app.timeOffsetConstUbText.Tooltip = 'Parameter upper bound.';
            app.timeOffsetConstUbText.Value = '0.0';
            app.angle1Panel = uipanel(app.GridLayout3);
            app.angle1Panel.Title = 'Throttle';
            app.angle1Panel.Tag = 'angle1Panel';
            app.GridLayout5 = uigridlayout(app.angle1Panel);
            app.text24 = uilabel(app.GridLayout5);
            app.text24.Tag = 'text24';
            app.text24.Tooltip = 'Where t is time since the start of this steering law plus the time offset, t = t0 + tOffset.';
            app.text24.Text = 'throttle = const + linear*t + (1/2)*accel*t^2';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.text5 = uilabel(app.GridLayout6);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Constant Term';
            app.text7 = uilabel(app.GridLayout6);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Linear Term';
            app.text9 = uilabel(app.GridLayout6);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Accel. Term';
            app.throttleConstTermText = uieditfield(app.GridLayout6, 'text');
            app.throttleConstTermText.ValueChangedFcn = createCallbackFcn(app, @throttleConstTermText_Callback, true);
            app.throttleConstTermText.Tag = 'throttleConstTermText';
            app.throttleConstTermText.Value = '0.0';
            app.throttleLinTermText = uieditfield(app.GridLayout6, 'text');
            app.throttleLinTermText.ValueChangedFcn = createCallbackFcn(app, @throttleLinTermText_Callback, true);
            app.throttleLinTermText.Tag = 'throttleLinTermText';
            app.throttleLinTermText.Value = '0.0';
            app.throttleAccelTermText = uieditfield(app.GridLayout6, 'text');
            app.throttleAccelTermText.ValueChangedFcn = createCallbackFcn(app, @throttleAccelTermText_Callback, true);
            app.throttleAccelTermText.Tag = 'throttleAccelTermText';
            app.throttleAccelTermText.Value = '0.0';
            app.text6 = uilabel(app.GridLayout6);
            app.text6.Tag = 'text6';
            app.text6.Tooltip = 'Is actually the throttle value from 0-1, but that''s hard to convey in a unit sense.';
            app.text6.Text = '%';
            app.text8 = uilabel(app.GridLayout6);
            app.text8.Tag = 'text8';
            app.text8.Tooltip = 'Is actually the throttle value from 0-1, but that''s hard to convey in a unit sense.';
            app.text8.Text = '%/s';
            app.text10 = uilabel(app.GridLayout6);
            app.text10.Tag = 'text10';
            app.text10.Tooltip = 'Is actually the throttle value from 0-1, but that''s hard to convey in a unit sense.';
            app.text10.Text = '%/s/s';
            app.throttleConstOptCheckbox = uicheckbox(app.GridLayout6);
            app.throttleConstOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @throttleConstOptCheckbox_Callback, true);
            app.throttleConstOptCheckbox.Tag = 'throttleConstOptCheckbox';
            app.throttleConstOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.throttleConstOptCheckbox.Text = 'Opt?';
            app.throttleConstOptCheckbox.Value = true;
            app.throttleLinOptCheckbox = uicheckbox(app.GridLayout6);
            app.throttleLinOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @throttleLinOptCheckbox_Callback, true);
            app.throttleLinOptCheckbox.Tag = 'throttleLinOptCheckbox';
            app.throttleLinOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.throttleLinOptCheckbox.Text = 'Opt?';
            app.throttleLinOptCheckbox.Value = true;
            app.throttleAccelOptCheckbox = uicheckbox(app.GridLayout6);
            app.throttleAccelOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @throttleAccelOptCheckbox_Callback, true);
            app.throttleAccelOptCheckbox.Tag = 'throttleAccelOptCheckbox';
            app.throttleAccelOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.throttleAccelOptCheckbox.Text = 'Opt?';
            app.throttleAccelOptCheckbox.Value = true;
            app.throttleConstLbText = uieditfield(app.GridLayout6, 'text');
            app.throttleConstLbText.ValueChangedFcn = createCallbackFcn(app, @throttleConstLbText_Callback, true);
            app.throttleConstLbText.Tag = 'throttleConstLbText';
            app.throttleConstLbText.Tooltip = 'Parameter lower bound.';
            app.throttleConstLbText.Value = '0.0';
            app.throttleLinLbText = uieditfield(app.GridLayout6, 'text');
            app.throttleLinLbText.ValueChangedFcn = createCallbackFcn(app, @throttleLinLbText_Callback, true);
            app.throttleLinLbText.Tag = 'throttleLinLbText';
            app.throttleLinLbText.Tooltip = 'Parameter lower bound.';
            app.throttleLinLbText.Value = '0.0';
            app.throttleAccelLbText = uieditfield(app.GridLayout6, 'text');
            app.throttleAccelLbText.ValueChangedFcn = createCallbackFcn(app, @throttleAccelLbText_Callback, true);
            app.throttleAccelLbText.Tag = 'throttleAccelLbText';
            app.throttleAccelLbText.Tooltip = 'Parameter lower bound.';
            app.throttleAccelLbText.Value = '0.0';
            app.throttleConstUbText = uieditfield(app.GridLayout6, 'text');
            app.throttleConstUbText.ValueChangedFcn = createCallbackFcn(app, @throttleConstUbText_Callback, true);
            app.throttleConstUbText.Tag = 'throttleConstUbText';
            app.throttleConstUbText.Tooltip = 'Parameter upper bound.';
            app.throttleConstUbText.Value = '0.0';
            app.throttleLinUbText = uieditfield(app.GridLayout6, 'text');
            app.throttleLinUbText.ValueChangedFcn = createCallbackFcn(app, @throttleLinUbText_Callback, true);
            app.throttleLinUbText.Tag = 'throttleLinUbText';
            app.throttleLinUbText.Tooltip = 'Parameter upper bound.';
            app.throttleLinUbText.Value = '0.0';
            app.throttleAccelUbText = uieditfield(app.GridLayout6, 'text');
            app.throttleAccelUbText.ValueChangedFcn = createCallbackFcn(app, @throttleAccelUbText_Callback, true);
            app.throttleAccelUbText.Tag = 'throttleAccelUbText';
            app.throttleAccelUbText.Tooltip = 'Parameter upper bound.';
            app.throttleAccelUbText.Value = '0.0';
            % Show the figure after all components are created
            app.lvd_EditActionSetThrottleModelGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditActionSetThrottleModelGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditActionSetThrottleModelGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditActionSetThrottleModelGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditActionSetThrottleModelGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditActionSetThrottleModelGUI)
        end
    end
end