classdef lvd_EditT2WThrottleModelGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditT2WThrottleModelGUI  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        uipanel1                     matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        GridLayout4                  matlab.ui.container.GridLayout
        twUbText                     matlab.ui.control.EditField
        twLbText                     matlab.ui.control.EditField
        twOptCheckbox                matlab.ui.control.CheckBox
        text4                        matlab.ui.control.Label
        twRatioText                  matlab.ui.control.EditField
        text3                        matlab.ui.control.Label
        throttleContCheckbox         matlab.ui.control.CheckBox
        GridLayout2                  matlab.ui.container.GridLayout
        cancelButton                 matlab.ui.control.Button
        saveAndCloseButton           matlab.ui.control.Button
        text2                        matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, action, useContinuity)
            throttleModel = action.throttleModel;

            handles.twRatioText.String = fullAccNum2Str(throttleModel.targetT2W);

            optVar = throttleModel.getExistingOptVar();
            if(isempty(optVar))
                useTf = false([1,1]);
                lb = zeros(size(useTf));
                ub = zeros(size(useTf));
            else
                useTf = optVar.getUseTfForVariable();
                optVar.setUseTfForVariable(true(size(useTf)));
                [lb, ub] = optVar.getBndsForVariable();
                optVar.setUseTfForVariable(useTf);
            end

            set(handles.twOptCheckbox,'Value',useTf);
            set(handles.twLbText,'String',fullAccNum2Str(lb));
            set(handles.twUbText,'String',fullAccNum2Str(ub));

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            twOptCheckbox_Callback(app, evt);

            if(useContinuity)
                handles.throttleContCheckbox.Enable = 'on';
            else
                handles.throttleContCheckbox.Enable = 'off';
            end
            handles.throttleContCheckbox.Value = double(throttleModel.throttleContinuity);
            throttleContCheckbox_Callback(app, evt);
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            twRatio = str2double(get(handles.twRatioText,'String'));
            enteredStr = get(handles.twRatioText,'String');
            numberName = sprintf('Thrust to Weight Ratio');
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(twRatio, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(handles.twOptCheckbox.Value == 1)
                twRatioLB = str2double(get(handles.twLbText,'String'));
                enteredStr = get(handles.twLbText,'String');
                numberName = sprintf('Thrust to Weight Ratio Lower Bound');
                lb = 0;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(twRatioLB, numberName, lb, ub, isInt, errMsg, enteredStr);

                twRatioUB = str2double(get(handles.twUbText,'String'));
                enteredStr = get(handles.twUbText,'String');
                numberName = sprintf('Thrust to Weight Ratio Upper Bound');
                lb = 0;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(twRatioUB, numberName, lb, ub, isInt, errMsg, enteredStr);


                if(isempty(errMsg))
                    if(twRatioLB > twRatioUB)
                        errMsg{end+1} = 'The lower bound must be less than or equal to the upper bound.';
                    end

                    if(twRatio < twRatioLB || twRatio > twRatioUB)
                        errMsg{end+1} = 'Throttle thrust to weight ratio must be between the upper and lower optimization bounds.';
                    end
                end
            end
        end


        function saveAndClose(app, handles)
            lv = getappdata(handles.lvd_EditT2WThrottleModelGUI,'lv');
            lvdData = lv.lvdData;

            action = getappdata(handles.lvd_EditT2WThrottleModelGUI,'action');
            throttleModel = action.throttleModel;

            optVar = throttleModel.getExistingOptVar();
            if(not(isempty(optVar))) %need to remove existing var if it exists
                lvdData.optimizer.vars.removeVariable(optVar);
            end

            throttleModel.targetT2W = str2double(handles.twRatioText.String);
            throttleModel.throttleContinuity = logical(handles.throttleContCheckbox.Value);

            if(isempty(optVar))
                optVar = throttleModel.getNewOptVar();
            end

            useTf = get(handles.twOptCheckbox,'Value');
            optVar.setUseTfForVariable(useTf);

            lb = str2double(get(handles.twLbText,'String'));
            ub = str2double(get(handles.twUbText,'String'));

            if(isnan(lb))
                lb = 0;
            end

            if(isnan(ub))
                ub = 0;
            end

            optVar.setUseTfForVariable(true(size(lb))); %need this to get the full lb/set in there
            optVar.setBndsForVariable(lb, ub);
            optVar.setUseTfForVariable(useTf);

            lvdData.optimizer.vars.addVariable(optVar);

            app.output.output = {true};
            close(handles.lvd_EditT2WThrottleModelGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditT2WThrottleModelGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditT2WThrottleModelGUI (see VARARGIN)

            % Choose default command line output for lvd_EditT2WThrottleModelGUI
            handles.output = hObject;

            centerUIFigure(hObject);

            action = varargin{1};
            setappdata(hObject,'action',action);

            lv = varargin{2};
            setappdata(hObject,'lv',lv);

            useContinuity = varargin{3};
            setappdata(hObject,'useContinuity',useContinuity);

            app.output = varargin{4};

            populateGUI(app, handles, action, useContinuity);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditT2WThrottleModelGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditT2WThrottleModelGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditT2WThrottleModelGUI);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                %                     uiresume(handles.lvd_EditT2WThrottleModelGUI);
                app.saveAndClose(handles);
            else
                %                     msgbox(errMsg,'Errors were found while editing the throttle model.','error');
                uialert(app.lvd_EditT2WThrottleModelGUI, errMsg, 'Errors were found while editing the throttle model.', "Icon","error");
            end
        end

        % Value changed function: throttleContCheckbox
        function throttleContCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.throttleContCheckbox;
            
            % hObject    handle to throttleContCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of throttleContCheckbox
            if(hObject.Value)
                handles.twRatioText.Enable = 'off';
                handles.twOptCheckbox.Enable = 'off';
                handles.twLbText.Enable = 'off';
                handles.twUbText.Enable = 'off';

                handles.twOptCheckbox.Value = 0;
            else
                handles.twRatioText.Enable = 'on';
                handles.twOptCheckbox.Enable = 'on';

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                twOptCheckbox_Callback(app, evt);
            end
        end

        % Value changed function: twLbText
        function twLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to twLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of twLbText as text
            %        str2double(get(hObject,'String')) returns contents of twLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: twOptCheckbox
        function twOptCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.twOptCheckbox;

            % hObject    handle to twOptCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of twOptCheckbox
            if(get(hObject,'Value')==1 && handles.throttleContCheckbox.Value == 0)
                set(handles.twLbText,'Enable','on');
                set(handles.twUbText,'Enable','on');
            else
                set(handles.twLbText,'Enable','off');
                set(handles.twUbText,'Enable','off');
            end
        end

        % Value changed function: twRatioText
        function twRatioText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to twRatioText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of twRatioText as text
            %        str2double(get(hObject,'String')) returns contents of twRatioText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: twUbText
        function twUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to twUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of twUbText as text
            %        str2double(get(hObject,'String')) returns contents of twUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditT2WThrottleModelGUI = uifigure('Visible', 'off');
            app.lvd_EditT2WThrottleModelGUI.Name = 'Edit Throttle Model';
            app.lvd_EditT2WThrottleModelGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditT2WThrottleModelGUI.Tag = 'lvd_EditT2WThrottleModelGUI';
            app.GridLayout = uigridlayout(app.lvd_EditT2WThrottleModelGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Throttle Model';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.throttleContCheckbox = uicheckbox(app.GridLayout3);
            app.throttleContCheckbox.ValueChangedFcn = createCallbackFcn(app, @throttleContCheckbox_Callback, true);
            app.throttleContCheckbox.Tag = 'throttleContCheckbox';
            app.throttleContCheckbox.Tooltip = 'When checked, the target thrust to weight ratio is replaced with the T2W ratio of the vehicle when this action is executed.';
            app.throttleContCheckbox.Text = 'Preserve throttle continuity at the start of this Throttle Model?';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.text3 = uilabel(app.GridLayout4);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Thrust to Weight';
            app.twRatioText = uieditfield(app.GridLayout4, 'text');
            app.twRatioText.ValueChangedFcn = createCallbackFcn(app, @twRatioText_Callback, true);
            app.twRatioText.Tag = 'twRatioText';
            app.twRatioText.Value = '1';
            app.text4 = uilabel(app.GridLayout4);
            app.text4.Tag = 'text4';
            app.text4.Text = '';
            app.twOptCheckbox = uicheckbox(app.GridLayout4);
            app.twOptCheckbox.ValueChangedFcn = createCallbackFcn(app, @twOptCheckbox_Callback, true);
            app.twOptCheckbox.Tag = 'twOptCheckbox';
            app.twOptCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.twOptCheckbox.Text = 'Opt?';
            app.twOptCheckbox.Value = true;
            app.twLbText = uieditfield(app.GridLayout4, 'text');
            app.twLbText.ValueChangedFcn = createCallbackFcn(app, @twLbText_Callback, true);
            app.twLbText.Tag = 'twLbText';
            app.twLbText.Tooltip = 'Parameter lower bound.';
            app.twLbText.Value = '0.0';
            app.twUbText = uieditfield(app.GridLayout4, 'text');
            app.twUbText.ValueChangedFcn = createCallbackFcn(app, @twUbText_Callback, true);
            app.twUbText.Tag = 'twUbText';
            app.twUbText.Tooltip = 'Parameter upper bound.';
            app.twUbText.Value = '0.0';
            % Show the figure after all components are created
            app.lvd_EditT2WThrottleModelGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditT2WThrottleModelGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditT2WThrottleModelGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditT2WThrottleModelGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditT2WThrottleModelGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditT2WThrottleModelGUI)
        end
    end
end