classdef lvd_EditTabularInterpThrottleModelGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SetThrottleModelUIFigure        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        cancelButton                    matlab.ui.control.Button
        saveAndCloseButton              matlab.ui.control.Button
        GridLayout3                     matlab.ui.container.GridLayout
        SubequentThrottleSettingsPanel  matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        GridLayout11                    matlab.ui.container.GridLayout
        InterpolationModelDropDown      matlab.ui.control.DropDown
        InterpolationModelDropDownLabel  matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        ThrottleSettingsTable           matlab.ui.control.Table
        GridLayout10                    matlab.ui.container.GridLayout
        RemoveRowButton                 matlab.ui.control.Button
        AddRowButton                    matlab.ui.control.Button
        InitialThrottleSettingPanel     matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        throttleContCheckbox            matlab.ui.control.CheckBox
        GridLayout5                     matlab.ui.container.GridLayout
        InitThrottleUpperBoundEditField  matlab.ui.control.NumericEditField
        InitThrottleLowerBoundEditField  matlab.ui.control.NumericEditField
        optCheckbox                     matlab.ui.control.CheckBox
        text4                           matlab.ui.control.Label
        ThrottleEditField               matlab.ui.control.NumericEditField
        ThrottleEditFieldLabel          matlab.ui.control.Label
        titleLabel                      matlab.ui.control.Label
    end

    properties (Access = private)
        model ThrottleInterpolatedModel
        lv LaunchVehicle
        useContinuity(1,1) logical = false;
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app)
            %BOUNDS and OPT checkboxes
            optVar = app.model.getExistingOptVar();
            if(isempty(optVar))
                optVar = app.model.getNewOptVar(); %then create a new var and use this one instead.
            end

            app.ThrottleEditField.Value = 100*app.model.initThrottle;
            app.optCheckbox.Value = optVar.varThrottle0;
            app.InitThrottleLowerBoundEditField.Value = 100*optVar.lbThrottle0;
            app.InitThrottleUpperBoundEditField.Value = 100*optVar.ubThrottle0;

            app.throttleContCheckbox.Value = app.model.throttleContinuity;

            [listboxStr, m] = ThrottleInterpolatedModelInterpTypeEnum.getThrottleModelTypeNameStrs();
            app.InterpolationModelDropDown.Items = listboxStr;
            app.InterpolationModelDropDown.ItemsData = m;
            app.InterpolationModelDropDown.Value = app.model.interpolationType;

            app.setInitBoundsEnables();
            app.setContinuityEnables();
            app.setTableFromSteeringModel();
            app.setDeleteRowButtonEnable();
        end

        function setTableFromSteeringModel(app)
            app.ThrottleSettingsTable.ColumnFormat = {'numeric', 'logical', 'numeric', 'numeric', ...
                                            'numeric', 'logical', 'numeric', 'numeric'};

            app.ThrottleSettingsTable.Data = app.makeTableDataFromModel();
        end

        function tableData = makeTableDataFromModel(app)
            tableData = {};

            tDurs = app.model.durations(:);
            throttles = app.model.throttles(:);

            optVar = app.model.getExistingOptVar();
            if(isempty(optVar)) %need to remove existing var if it exists
                optVar = app.model.getNewOptVar(); %then create a new var and use this one instead.
            end

            % Defensive: the model and its variable can drift apart when
            % durations are assigned directly or loaded from older files.
            % getExistingOptVar resyncs the variable, but guard the table
            % build itself so the dialog never crashes on open.
            nRows = max(numel(tDurs), numel(throttles));
            if(nRows == 0)
                return;
            end
            if(numel(tDurs) < nRows)
                tDurs(end+1:nRows, 1) = 1;
            end
            if(numel(throttles) < nRows)
                throttles(end+1:nRows, 1) = 0;
            end

            varTDur = optVar.varTDur(:);
            varThrottles = optVar.varThrottles(:);
            lbTDur = optVar.lbTDur(:);
            lbThrottles = optVar.lbThrottles(:);
            ubTDur = optVar.ubTDur(:);
            ubThrottles = optVar.ubThrottles(:);
            if(numel(varTDur) < nRows)
                varTDur(end+1:nRows, 1) = false;
            end
            if(numel(varThrottles) < nRows)
                varThrottles(end+1:nRows, 1) = false;
            end
            if(numel(lbTDur) < nRows)
                lbTDur(end+1:nRows, 1) = 0;
            end
            if(numel(lbThrottles) < nRows)
                lbThrottles(end+1:nRows, 1) = 0;
            end
            if(numel(ubTDur) < nRows)
                ubTDur(end+1:nRows, 1) = 1;
            end
            if(numel(ubThrottles) < nRows)
                ubThrottles(end+1:nRows, 1) = 1;
            end

            for(i=1:nRows)
                tableData(i,:) = {tDurs(i),         varTDur(i),        lbTDur(i),            ubTDur(i), ...
                                  throttles(i)*100, varThrottles(i),   lbThrottles(i)*100,   ubThrottles(i)*100}; %#ok<AGROW>
            end
        end

        function addRowToTableData(app)
            tableData = app.ThrottleSettingsTable.Data;

            tableData(end+1,:) = {1, false, 0, 1, ...
                                  0, false, 0, 100};

            app.ThrottleSettingsTable.Data = tableData;
        end

        function removeRowFromTableData(app, rowNum)
            tableData = app.ThrottleSettingsTable.Data;
            tableData(rowNum,:) = [];
            app.ThrottleSettingsTable.Data = tableData;
        end

        function setInitBoundsEnables(app)
            if(app.optCheckbox.Value)
                app.InitThrottleLowerBoundEditField.Enable = "on";
                app.InitThrottleUpperBoundEditField.Enable = "on";
            else
                app.InitThrottleLowerBoundEditField.Enable = "off";
                app.InitThrottleUpperBoundEditField.Enable = "off";
            end
        end

        function setDeleteRowButtonEnable(app)
            tableData = app.ThrottleSettingsTable.Data;
            if(height(tableData) > 1)
                app.RemoveRowButton.Enable = "on";
            else
                app.RemoveRowButton.Enable = "off";
            end
        end

        function errMsg = validateInputs(app)
            errMsg = {};

            %%%% Intial Throttle
            val = app.InitThrottleLowerBoundEditField.Value;
            enteredStr = fullAccNum2Str(val);
            numberName = sprintf('Initial Throttle Setting Lower Bound');
            lb = 0;
            ub = app.InitThrottleUpperBoundEditField.Value;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);

            val = app.ThrottleEditField.Value;
            enteredStr = fullAccNum2Str(val);
            numberName = sprintf('Initial Throttle Setting');
            lb = app.InitThrottleLowerBoundEditField.Value;
            ub = app.InitThrottleUpperBoundEditField.Value;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);

            %%%% Tabular Attitudes
            tableData = app.ThrottleSettingsTable.Data;

            if(isempty(tableData))
                errMsg{end+1} = 'The throttle table must have at least one row.';
            end

            %%% Tabular Attitude Values
            if(not(isempty(tableData)))
                % Check Durations
                for(i=1:height(tableData))
                    valLb = tableData{i,3};
                    enteredStr = fullAccNum2Str(valLb);
                    numberName = sprintf('Throttle %u Segment Duration Lower Bound', i);
                    lb = 0;
                    ub = Inf;
                    isInt = false;
                    errMsg = validateNumber(valLb, numberName, lb, ub, isInt, errMsg, enteredStr);

                    valUb = tableData{i,4};
                    enteredStr = fullAccNum2Str(valUb);
                    numberName = sprintf('Throttle %u Segment Duration Upper Bound', i);
                    lb = valLb;
                    ub = Inf;
                    isInt = false;
                    errMsg = validateNumber(valUb, numberName, lb, ub, isInt, errMsg, enteredStr);

                    val = tableData{i,1};
                    enteredStr = fullAccNum2Str(val);
                    numberName = sprintf('Throttle %u Segment Duration', i);
                    lb = valLb;
                    ub = valUb;
                    isInt = false;
                    errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
                end

                % Check Throttles
                for(i=1:height(tableData))
                    valLb = tableData{i,7};
                    enteredStr = fullAccNum2Str(valLb);
                    numberName = sprintf('Throttle %u Setting Lower Bound', i);
                    lb = 0;
                    ub = 100;
                    isInt = false;
                    errMsg = validateNumber(valLb, numberName, lb, ub, isInt, errMsg, enteredStr);

                    valUb = tableData{i,8};
                    enteredStr = fullAccNum2Str(valUb);
                    numberName = sprintf('Throttle %u Setting Upper Bound', i);
                    lb = valLb;
                    ub = 100;
                    isInt = false;
                    errMsg = validateNumber(valUb, numberName, lb, ub, isInt, errMsg, enteredStr);

                    val = tableData{i,5};
                    enteredStr = fullAccNum2Str(val);
                    numberName = sprintf('Throttle %u Setting', i);
                    lb = valLb;
                    ub = valUb;
                    isInt = false;
                    errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
                end
            end
        end

        function saveAndClose(app)
            lvdData = app.lv.lvdData;

            throttleModel = app.model;

            optVar = throttleModel.getExistingOptVar();
            if(not(isempty(optVar))) %need to remove existing var if it exists
                lvdData.optimizer.vars.removeVariable(optVar);
            end
            optVar = throttleModel.getNewOptVar(); %then create a new var and use this one instead

            contTf = logical(app.throttleContCheckbox.Value);
            throttleModel.throttleContinuity = contTf;

            %%% Initial Throttle (Values) 
            throttleModel.initThrottle = app.ThrottleEditField.Value/100;

            %%% Interp Type
            app.model.interpolationType = app.InterpolationModelDropDown.Value;

            %%% Tabular Angles
            tableData = app.ThrottleSettingsTable.Data;

            throttleModel.durations     = cell2mat(tableData(:, 1));
            throttleModel.throttles     = cell2mat(tableData(:, 5))/100; 

            %%% Assemble Opt Flags
            useTf = [app.optCheckbox.Value; ...
                     cell2mat(tableData(:,2));
                     cell2mat(tableData(:,6))];

            %%% Assemble Lower Bounds
            lb = [app.InitThrottleLowerBoundEditField.Value/100; ...
                  cell2mat(tableData(:,3));
                  cell2mat(tableData(:,7))/100];

            %%% Assemble Upper Bounds
            ub = [app.InitThrottleUpperBoundEditField.Value/100; ...
                  cell2mat(tableData(:,4));
                  cell2mat(tableData(:,8))/100];

            optVar.setUseTfForVariable(true(size(lb))); %need this to get the full lb/set in there
            optVar.setBndsForVariable(lb, ub);
            optVar.setUseTfForVariable(useTf);

            lvdData.optimizer.vars.addVariable(optVar);

            app.output.output = {true, throttleModel};
            delete(app.SetThrottleModelUIFigure);
        end
        
        function setContinuityEnables(app)
            if(app.throttleContCheckbox.Value)
                app.ThrottleEditField.Enable = "off";
                app.InitThrottleLowerBoundEditField.Enable = "off";
                app.InitThrottleUpperBoundEditField.Enable = "off";
                app.optCheckbox.Enable = "off";
            else
                app.ThrottleEditField.Enable = "on";
                app.InitThrottleLowerBoundEditField.Enable = "on";
                app.InitThrottleUpperBoundEditField.Enable = "on";
                app.optCheckbox.Enable = "on";

                app.setInitBoundsEnables();
            end 
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, model, lv, useContinuity, output)
            app.model = model;
            app.lv = lv;
            app.useContinuity = useContinuity;
            app.output = output;

            centerUIFigure(app.SetThrottleModelUIFigure);
            applySelectedThemeToApp(app);

            app.populateGUI();

            uiwait(app.SetThrottleModelUIFigure);
        end

        % Button pushed function: AddRowButton
        function AddRowButtonPushed(app, event)
            app.addRowToTableData();

            app.setDeleteRowButtonEnable();
        end

        % Button pushed function: RemoveRowButton
        function RemoveRowButtonPushed(app, event)
            sel = app.ThrottleSettingsTable.Selection;
            if(isempty(sel))
                return;
            end
            rows = unique(sel(:, 1));
            app.removeRowFromTableData(rows);

            app.setDeleteRowButtonEnable();
        end

        % Value changed function: optCheckbox
        function optCheckboxValueChanged(app, event)
            app.setInitBoundsEnables();
        end

        % Value changed function: throttleContCheckbox
        function throttleContCheckboxValueChanged(app, event)
            app.setContinuityEnables();
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButtonPushed(app, event)
            errMsg = app.validateInputs();

            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.SetThrottleModelUIFigure, errMsg, 'Input Errors', "Icon","error");
            end
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            app.output.output = {false, app.model};

            delete(app.SetThrottleModelUIFigure);
        end

        % Close request function: SetThrottleModelUIFigure
        function SetThrottleModelUIFigureCloseRequest(app, event)
            % Only stamp a cancel when Save has not already committed.
            % saveAndClose deletes the figure directly so it never reaches
            % here, but a user X-close after a programmatic save must not
            % clobber the {true, model} output.
            if(isempty(app.output.output) || ~app.output.output{1})
                app.output.output = {false, app.model};
            end

            delete(app.SetThrottleModelUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SetThrottleModelUIFigure = uifigure('Visible', 'off');
            app.SetThrottleModelUIFigure.Name = 'Set Throttle Model';
            app.SetThrottleModelUIFigure.Icon = 'ksptot_logo4_transparent.png';
            app.SetThrottleModelUIFigure.CloseRequestFcn = createCallbackFcn(app, @SetThrottleModelUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.SetThrottleModelUIFigure);
            app.titleLabel = uilabel(app.GridLayout);
            app.titleLabel.Tag = 'titleLabel';
            app.titleLabel.Text = 'Edit Throttle Model';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.InitialThrottleSettingPanel = uipanel(app.GridLayout3);
            app.InitialThrottleSettingPanel.Title = 'Initial Throttle Setting';
            app.GridLayout4 = uigridlayout(app.InitialThrottleSettingPanel);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.ThrottleEditFieldLabel = uilabel(app.GridLayout5);
            app.ThrottleEditFieldLabel.Text = 'Throttle';
            app.ThrottleEditField = uieditfield(app.GridLayout5, 'numeric');
            app.ThrottleEditField.Limits = [0 100];
            app.ThrottleEditField.ValueDisplayFormat = '%.3f';
            app.text4 = uilabel(app.GridLayout5);
            app.text4.Tag = 'text4';
            app.text4.Text = '%';
            app.optCheckbox = uicheckbox(app.GridLayout5);
            app.optCheckbox.ValueChangedFcn = createCallbackFcn(app, @optCheckboxValueChanged, true);
            app.optCheckbox.Tag = 'twOptCheckbox';
            app.optCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.optCheckbox.Text = 'Opt?';
            app.optCheckbox.Value = true;
            app.InitThrottleLowerBoundEditField = uieditfield(app.GridLayout5, 'numeric');
            app.InitThrottleLowerBoundEditField.Limits = [0 100];
            app.InitThrottleLowerBoundEditField.ValueDisplayFormat = '%.3f';
            app.InitThrottleUpperBoundEditField = uieditfield(app.GridLayout5, 'numeric');
            app.InitThrottleUpperBoundEditField.Limits = [0 100];
            app.InitThrottleUpperBoundEditField.ValueDisplayFormat = '%.3f';
            app.throttleContCheckbox = uicheckbox(app.GridLayout4);
            app.throttleContCheckbox.ValueChangedFcn = createCallbackFcn(app, @throttleContCheckboxValueChanged, true);
            app.throttleContCheckbox.Tag = 'throttleContCheckbox';
            app.throttleContCheckbox.Tooltip = 'When checked, the target thrust to weight ratio is replaced with the T2W ratio of the vehicle when this action is executed.';
            app.throttleContCheckbox.Text = 'Preserve throttle continuity at the start of this Throttle Model?';
            app.SubequentThrottleSettingsPanel = uipanel(app.GridLayout3);
            app.SubequentThrottleSettingsPanel.Title = 'Subequent Throttle Settings';
            app.GridLayout6 = uigridlayout(app.SubequentThrottleSettingsPanel);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.GridLayout10 = uigridlayout(app.GridLayout7);
            app.AddRowButton = uibutton(app.GridLayout10, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowButtonPushed, true);
            app.AddRowButton.Icon = 'add.png';
            app.AddRowButton.Tooltip = {'Add new row to table.'};
            app.AddRowButton.Text = '';
            app.RemoveRowButton = uibutton(app.GridLayout10, 'push');
            app.RemoveRowButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRowButtonPushed, true);
            app.RemoveRowButton.Icon = 'remove.png';
            app.RemoveRowButton.Tooltip = {'Remove selected row from table.  There must be at least one row in the table.'};
            app.RemoveRowButton.Text = '';
            app.ThrottleSettingsTable = uitable(app.GridLayout7);
            app.ThrottleSettingsTable.ColumnName = {'Duration [s]'; 'Duration Opt?'; 'Duration LB [s]'; 'Duration UB [s]'; 'Throttle [%]'; 'Throttle Opt?'; 'Throttle LB [%]'; 'Throttle UB [%]'};
            app.ThrottleSettingsTable.RowName = {};
            app.ThrottleSettingsTable.ColumnEditable = true;
            app.GridLayout11 = uigridlayout(app.GridLayout6);
            app.InterpolationModelDropDownLabel = uilabel(app.GridLayout11);
            app.InterpolationModelDropDownLabel.Text = 'Interpolation Model';
            app.InterpolationModelDropDown = uidropdown(app.GridLayout11);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButtonPushed, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.SetThrottleModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditTabularInterpThrottleModelGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SetThrottleModelUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SetThrottleModelUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SetThrottleModelUIFigure)
        end
    end
end