classdef lvd_EditThrottleModelsSet_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditThrottleModelsUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        ThrottleModelPanel          matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        ModelDescLabel              matlab.ui.control.Label
        EditThrottleModelButton     matlab.ui.control.Button
        GridLayout4                 matlab.ui.container.GridLayout
        ThrottleModelLabel          matlab.ui.control.Label
        ThrottleModelCombo          matlab.ui.control.DropDown
        GridLayout2                 matlab.ui.container.GridLayout
        cancelButton                matlab.ui.control.Button
        saveCloseButton             matlab.ui.control.Button
        text2                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput
        lvdData LvdData
        throttleModels ThrottleModelsSet
        allowContinuity(1,1) logical = true;
    end
    
    methods (Access = private)
        function populateGUI(app)
            [listBoxStr, m] = ThrottleModelEnum.getThrottleModelTypeNameStrs();
            app.ThrottleModelCombo.Items = listBoxStr;
            app.ThrottleModelCombo.ItemsData = m;
            app.ThrottleModelCombo.Value = app.throttleModels.selectedModel.getThrottleModelTypeEnum();

            app.ModelDescLabel.Text = app.ThrottleModelCombo.Value.desc;
        end

        function saveAndClose(app)
            enum = app.ThrottleModelCombo.Value;
            switch enum
                case ThrottleModelEnum.PolyModel
                    app.throttleModels.selectedModel = app.throttleModels.polyThrottle;
                    otherModels = [app.throttleModels.t2wThrottle, app.throttleModels.tabularInterpThrottle];

                case ThrottleModelEnum.T2WModel
                    app.throttleModels.selectedModel = app.throttleModels.t2wThrottle;
                    otherModels = [app.throttleModels.polyThrottle, app.throttleModels.tabularInterpThrottle];

                case ThrottleModelEnum.InterpThrottle
                    app.throttleModels.selectedModel = app.throttleModels.tabularInterpThrottle;
                    otherModels = [app.throttleModels.polyThrottle, app.throttleModels.t2wThrottle];

                otherwise
                    error('Unknown throttle model type: %s', enum.name);
            end

            %need to turn off optimization variables for other models
            for(i=1:length(otherModels)) %#ok<*NO4LP> 
                oModelOptVar = otherModels(i).getExistingOptVar();

                if(not(isempty(oModelOptVar)))
                    useTf = oModelOptVar.getUseTfForVariable();
                    oModelOptVar.setUseTfForVariable(false(size(useTf)));
                end
            end

            app.output.output{1} = true;
            close(app.EditThrottleModelsUIFigure);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, throttleModels, lvdData, out, allowContinuity)
            centerUIFigure(app.EditThrottleModelsUIFigure);
            applySelectedThemeToApp(app);
            
            app.throttleModels = throttleModels;
            app.lvdData = lvdData;
            app.output = out;
            app.allowContinuity = allowContinuity;

            app.populateGUI();

            uiwait(app.EditThrottleModelsUIFigure);
        end

        % Button pushed function: EditThrottleModelButton
        function EditThrottleModelButtonPushed(app, event)
            lv = app.lvdData.launchVehicle;
            
            enum = app.ThrottleModelCombo.Value;
            switch enum
                case ThrottleModelEnum.PolyModel
                    [~, throttleModel] = app.throttleModels.polyThrottle.openEditThrottleModelUI(lv, app.allowContinuity);
                    app.throttleModels.polyThrottle = throttleModel;

                case ThrottleModelEnum.T2WModel
                    [~, throttleModel] = app.throttleModels.t2wThrottle.openEditThrottleModelUI(lv, app.allowContinuity);
                    app.throttleModels.t2wThrottle = throttleModel;

                case ThrottleModelEnum.InterpThrottle
                    [~, throttleModel] = app.throttleModels.tabularInterpThrottle.openEditThrottleModelUI(lv, app.allowContinuity);
                    app.throttleModels.tabularInterpThrottle = throttleModel;

                otherwise
                    error('Unknown throttle model type: %s', enum.name);
            end
        end

        % Button pushed function: saveCloseButton
        function saveCloseButtonPushed(app, event)
            errMsg = {};
            
            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.EditThrottleModelsUIFigure, errMsg, 'Errors were found while editing the throttle model.', "Icon","error");
            end 
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            app.output.output = {false};
            close(app.EditThrottleModelsUIFigure);
        end

        % Value changed function: ThrottleModelCombo
        function ThrottleModelComboValueChanged(app, event)
            enum = app.ThrottleModelCombo.Value;
            app.ModelDescLabel.Text = enum.desc;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditThrottleModelsUIFigure = uifigure('Visible', 'off');
            app.EditThrottleModelsUIFigure.Name = 'Set Throttle Model';
            app.EditThrottleModelsUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout = uigridlayout(app.EditThrottleModelsUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Throttle Model';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveCloseButtonPushed, true);
            app.saveCloseButton.Tag = 'saveCloseButton';
            app.saveCloseButton.Icon = 'save-file.png';
            app.saveCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.ThrottleModelPanel = uipanel(app.GridLayout);
            app.ThrottleModelPanel.Title = 'Throttle Model';
            app.GridLayout3 = uigridlayout(app.ThrottleModelPanel);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.ThrottleModelCombo = uidropdown(app.GridLayout4);
            app.ThrottleModelCombo.ValueChangedFcn = createCallbackFcn(app, @ThrottleModelComboValueChanged, true);
            app.ThrottleModelLabel = uilabel(app.GridLayout4);
            app.ThrottleModelLabel.Text = 'Throttle Model';
            app.EditThrottleModelButton = uibutton(app.GridLayout3, 'push');
            app.EditThrottleModelButton.ButtonPushedFcn = createCallbackFcn(app, @EditThrottleModelButtonPushed, true);
            app.EditThrottleModelButton.Icon = 'throttle.png';
            app.EditThrottleModelButton.Text = 'Edit Throttle Model';
            app.ModelDescLabel = uilabel(app.GridLayout3);
            % Show the figure after all components are created
            app.EditThrottleModelsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditThrottleModelsSet_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditThrottleModelsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditThrottleModelsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditThrottleModelsUIFigure)
        end
    end
end