classdef editThrottleModifierProfileGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        editThrottleModifierProfileGUI  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        uipanel1                        matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        elementsListbox                 matlab.ui.control.ListBox
        GridLayout4                     matlab.ui.container.GridLayout
        deleteElementButton             matlab.ui.control.Button
        editElementButton               matlab.ui.control.Button
        addElementButton                matlab.ui.control.Button
        profileAxes                     matlab.ui.control.UIAxes
        saveAndCloseButton              matlab.ui.control.Button
        titleLabel                      matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function output_txt = dataTipFormatFunc(app, obj, event_obj)
            % Display the position of the data cursor
            % obj          Currently not used (empty)
            % event_obj    Handle to event object
            % output_txt   Data cursor text string (string or cell array of strings).
            pos = get(event_obj,'Position');
            output_txt = {['X: ',num2str(pos(1),'%12.5f')],...
                ['Y: ',num2str(pos(2),'%12.5f')]};

            % If there is a Z-coordinate in the position, display it as well
            if length(pos) > 2
                output_txt{end+1} = ['Z: ',num2str(pos(3),'%12.5f')];
            end
        end

        function elem = getSelectedElem(app, handles)
            throttleCurve = getappdata(handles.editThrottleModifierProfileGUI,'throttleCurve');
            [~, elemArr] = throttleCurve.getListboxStr();

            ind = get(handles.elementsListbox,'Value');
            elem = elemArr(ind);
        end

        function plotProfile(app, handles)
            throttleCurve = getappdata(handles.editThrottleModifierProfileGUI,'throttleCurve');
            [x, y] = throttleCurve.getPlotablePoints();

            indMin = throttleCurve.elems(1).minIndepValue;
            if(isnan(indMin))
                indMin = min(x);
            end

            indMax = throttleCurve.elems(1).maxIndepValue;
            if(isnan(indMax))
                indMax = max(x);
            end

            depMin = throttleCurve.elems(1).minDepValue;
            if(isnan(depMin))
                depMin = min(y);
            end

            depMax = throttleCurve.elems(1).maxDepValue;
            if(isnan(depMax))
                depMax = max(y);
            end

            xq = linspace(indMin,indMax,1000);
            yq = throttleCurve.evalCurve(xq);

            cla(handles.profileAxes,'reset');

            hold(handles.profileAxes,'on');
            axes(handles.profileAxes);
            plot(handles.profileAxes,x,y,'ro');
            plot(handles.profileAxes,xq,yq,'b-');
            grid(handles.profileAxes,'minor');
            xlabel(handles.profileAxes,sprintf('%s [%s]', throttleCurve.getIndepVarName(), throttleCurve.getIndepVarUnit()));
            ylabel(handles.profileAxes,sprintf('%s [%s]', throttleCurve.getDepVarName(), throttleCurve.getDepVarUnit()));
            xlim(handles.profileAxes,[indMin,indMax]);
            ylim(handles.profileAxes,[depMin,1.1*depMax]);
            hold(handles.profileAxes,'off');

            dcmObj = datacursormode;
            set(dcmObj,'UpdateFcn',@app.dataTipFormatFunc,'Enable','on');

            handles.elementsListbox.TooltipString = throttleCurve.getListboxTooltipStr();

            hManager = uigetmodemanager(handles.editThrottleModifierProfileGUI);
            try
                set(hManager.WindowListenerHandles, 'Enable', 'off');  % HG1
            catch
                [hManager.WindowListenerHandles.Enabled] = deal(false);  % HG2
            end

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            set(handles.editThrottleModifierProfileGUI, 'WindowKeyPressFcn', @(hObject, eventdata) editThrottleModifierProfileGUI_WindowKeyPressFcn(app, evt));
        end

        function populateUI(app, throttleCurve, handles)
            handles.titleLabel.String = sprintf('Edit %s Profile', throttleCurve.getCurveName());
            handles.elementsListbox.String = throttleCurve.getListboxStr();
            plotProfile(app, handles);
        end


        function saveAndClose(app, handles)
            app.output.output = {true};
            close(handles.editThrottleModifierProfileGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function editThrottleModifierProfileGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to editThrottleModifierProfileGUI (see VARARGIN)

            % Choose default command line output for editThrottleModifierProfileGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            throttleCurve = varargin{1};
            setappdata(hObject,'throttleCurve',throttleCurve);

            app.output = varargin{2};

            % Update handles structure
            guidata(hObject, handles);

            populateUI(app, throttleCurve, handles);

            % UIWAIT makes editThrottleModifierProfileGUI wait for user response (see UIRESUME)
            uiwait(handles.editThrottleModifierProfileGUI);
        end

        % Button pushed function: addElementButton
        function addElementButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addElementButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            throttleCurve = getappdata(handles.editThrottleModifierProfileGUI,'throttleCurve');

            elem = throttleCurve.createNewElement();
            
            output2 = AppDesignerGUIOutput({false});
            lvd_editThrottleModifierProfileElementGUI_App(elem, true, throttleCurve, output2);
            useTf = output2.output{1};
            
            if(useTf)
                throttleCurve = getappdata(handles.editThrottleModifierProfileGUI,'throttleCurve');
                throttleCurve.addElement(elem);

                populateUI(app, throttleCurve, handles);
            end
        end

        % Button pushed function: deleteElementButton
        function deleteElementButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to deleteElementButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            elem = getSelectedElem(app, handles);
            throttleCurve = getappdata(handles.editThrottleModifierProfileGUI,'throttleCurve');

            if(ismember(elem.indepVar, elem.mandatoryIndepValues))
                errordlg(sprintf('Cannot delete the endpoint elements at %.1f or %.1f.  The profile must have points at these two locations.',elem.minIndepValue,elem.maxIndepValue));
            else
                throttleCurve.removeElement(elem);
                populateUI(app, throttleCurve, handles);
            end
        end

        % Button pushed function: editElementButton
        function editElementButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editElementButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            throttleCurve = getappdata(handles.editThrottleModifierProfileGUI,'throttleCurve');
            elem = getSelectedElem(app, handles);

            if(ismember(elem.indepVar, elem.mandatoryIndepValues))
                allowIndVarEdit = false;
            else
                allowIndVarEdit = true;
            end

%             useTf = lvd_editThrottleModifierProfileElementGUI(elem, allowIndVarEdit, throttleCurve);
            
            output2 = AppDesignerGUIOutput({false});
            lvd_editThrottleModifierProfileElementGUI_App(elem, allowIndVarEdit, throttleCurve, output2);
            useTf = output2.output{1};            

            if(useTf)
                throttleCurve = getappdata(handles.editThrottleModifierProfileGUI,'throttleCurve');
                throttleCurve.generateCurve();
                populateUI(app, throttleCurve, handles);
            end
        end

        % Window key press function: editThrottleModifierProfileGUI
        function editThrottleModifierProfileGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editThrottleModifierProfileGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.editThrottleModifierProfileGUI);
            end
        end

        % Value changed function: elementsListbox
        function elementsListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to elementsListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns elementsListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from elementsListbox
            %             if(strcmpi(handles.editThrottleModifierProfileGUI.SelectionType,'open'))
            %                 evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            %                 editElementButton_Callback(app, evt);
            %             end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = {};
            %     errMsg = validateInputs(handles);

            if(isempty(errMsg))
                %                 uiresume(handles.editThrottleModifierProfileGUI);
                app.saveAndClose(handles);
            else
                %                 msgbox(errMsg,'Invalid Inputs','error');
                uialert(app.editThrottleModifierProfileGUI, errMsg, 'Invalid Inputs', "Icon","error");
            end
        end

        % Window button down function: editThrottleModifierProfileGUI
        function editThrottleModifierProfileGUIWindowButtonDown(app, event)
            selType = app.editThrottleModifierProfileGUI.SelectionType;
            curObj = app.editThrottleModifierProfileGUI.CurrentObject;

            if(strcmpi(selType,'open'))
                if(curObj == app.elementsListbox)
                    evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                    editElementButton_Callback(app, evt);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.editThrottleModifierProfileGUI = uifigure('Visible', 'off');
            app.editThrottleModifierProfileGUI.Name = 'Edit Throttle Modifier Profile';
            app.editThrottleModifierProfileGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.editThrottleModifierProfileGUI.WindowButtonDownFcn = createCallbackFcn(app, @editThrottleModifierProfileGUIWindowButtonDown, true);
            app.editThrottleModifierProfileGUI.WindowKeyPressFcn = createCallbackFcn(app, @editThrottleModifierProfileGUI_WindowKeyPressFcn, true);
            app.editThrottleModifierProfileGUI.Tag = 'editThrottleModifierProfileGUI';
            app.GridLayout = uigridlayout(app.editThrottleModifierProfileGUI);
            app.titleLabel = uilabel(app.GridLayout);
            app.titleLabel.Tag = 'titleLabel';
            app.titleLabel.Text = 'Edit Throttle Modifier Profile';
            app.saveAndCloseButton = uibutton(app.GridLayout, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.profileAxes = uiaxes(app.GridLayout2);
            app.profileAxes.Tag = 'profileAxes';
            app.uipanel1 = uipanel(app.GridLayout2);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.addElementButton = uibutton(app.GridLayout4, 'push');
            app.addElementButton.ButtonPushedFcn = createCallbackFcn(app, @addElementButton_Callback, true);
            app.addElementButton.Tag = 'addElementButton';
            app.addElementButton.Icon = 'add.png';
            app.addElementButton.Text = 'Add Element';
            app.editElementButton = uibutton(app.GridLayout4, 'push');
            app.editElementButton.ButtonPushedFcn = createCallbackFcn(app, @editElementButton_Callback, true);
            app.editElementButton.Tag = 'editElementButton';
            app.editElementButton.Icon = 'edit.png';
            app.editElementButton.Text = 'Edit Element';
            app.deleteElementButton = uibutton(app.GridLayout4, 'push');
            app.deleteElementButton.ButtonPushedFcn = createCallbackFcn(app, @deleteElementButton_Callback, true);
            app.deleteElementButton.Tag = 'deleteElementButton';
            app.deleteElementButton.Icon = 'remove.png';
            app.deleteElementButton.Text = 'Delete Element';
            app.elementsListbox = uilistbox(app.GridLayout3);
            app.elementsListbox.Items = {'Listbox'};
            app.elementsListbox.ValueChangedFcn = createCallbackFcn(app, @elementsListbox_Callback, true);
            app.elementsListbox.Tag = 'elementsListbox';
            app.elementsListbox.Tooltip = 'Elements of the throttle modifer profile.  There must be a point at 0% and 100% fuel remaining, and those elements cannot have their fuel remaining value edited.';
            app.elementsListbox.Value = 'Listbox';
            % Show the figure after all components are created
            app.editThrottleModifierProfileGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = editThrottleModifierProfileGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.editThrottleModifierProfileGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)editThrottleModifierProfileGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.editThrottleModifierProfileGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.editThrottleModifierProfileGUI)
        end
    end
end