classdef lvd_EditElectricalPowerSinksGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditElectricalPowerSinksGUI  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        Panel                    matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        GridLayout3              matlab.ui.container.GridLayout
        removeSinkButton         matlab.ui.control.Button
        addSinkButton            matlab.ui.control.Button
        sinkListBox              matlab.ui.control.ListBox
        closeButton              matlab.ui.control.Button
        text2                    matlab.ui.control.Label
        sinksListboxContextMenu  matlab.ui.container.ContextMenu
        copySinkMenu             matlab.ui.container.Menu
    end


    methods (Access = private)
        function stageState = getStageStateForPowerState(app, powerSink, lvdData)
            stage = powerSink.stage;
            stageStates = lvdData.initStateModel.stageStates;
            stageStateInd = find([stageStates.stage] == stage,1,'first');
            stageState = stageStates(stageStateInd);
        end

        function populateGUI(app, handles, lvdData)
            [~, ~] = app.setListboxStr();
        end

        function [powerSinkListStr, powerSinks] = setListboxStr(app)
            lvdData = getappdata(app.lvd_EditElectricalPowerSinksGUI,'lvdData');
            [powerSinkListStr, powerSinks] = lvdData.launchVehicle.getPowerSinksListBoxStr();
            app.sinkListBox.Items = powerSinkListStr;
            app.sinkListBox.ItemsData = powerSinks;
            
            num = numel(powerSinks);
            if(num <= 0)
                app.removeSinkButton.Enable = 'off';
            else
                app.removeSinkButton.Enable = 'on';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditElectricalPowerSinksGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditElectricalPowerSinksGUI (see VARARGIN)

            % Choose default command line output for lvd_EditElectricalPowerSinksGUI
            handles.output = hObject;
            
            centerUIFigure(app.lvd_EditElectricalPowerSinksGUI);
            applySelectedThemeToApp(app);

            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            populateGUI(app, handles, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditElectricalPowerSinksGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditElectricalPowerSinksGUI);
        end

        % Button pushed function: addSinkButton
        function addSinkButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addSinkButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditElectricalPowerSinksGUI,'lvdData');

            sinkListBoxStr = LaunchVehiclePowerSinkEnum.getListBoxStr();
            out = AppDesignerGUIOutput();
            listdlgARH_App('ListString', sinkListBoxStr, ...
                            'SelectionMode','single', ...
                            'Name', 'New Sink Type', ...
                            'PromptString','Select type of new sink:', ...
                            'ListSize',[300,300], ...
                            'out',out);
            Selection = out.output{1};
            ok = out.output{2};

            if(ok == 1)
                initStage = lvdData.launchVehicle.getStageForInd(1);

                sinkEnum = LaunchVehiclePowerSinkEnum.getEnumForListboxStr(sinkListBoxStr{Selection});
                switch sinkEnum
                    case LaunchVehiclePowerSinkEnum.Simple
                        powerSink = LaunchVehicleSimplePwrSink(initStage);

                    otherwise
                        error('Unknown Power Sink Type: %s', class(sinkEnum))
                end

                useTF = powerSink.openEditDialog();

                if(useTF)
                    powerSink.getAttachedStage().addPwrSink(powerSink);
                    
                    stageStates = lvdData.initStateModel.stageStates;
                    stageStateInd = find([stageStates.stage] == powerSink.stage,1,'first');
                    stageState = stageStates(stageStateInd);

                    newSinkState = newPowerSink.createDefaultInitialState(stageState);
                    stageState.addPowerSinkState(newSinkState);

                    [~, ~] = app.setListboxStr();
                end
            end
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to closeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditElectricalPowerSinksGUI);
        end

        % Menu selected function: copySinkMenu
        function copySinkMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to copySinkMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditElectricalPowerSinksGUI,'lvdData');
%             lv = lvdData.launchVehicle;

            powerSink = app.sinkListBox.Value;

            newPowerSink = powerSink.copy();
            initStage = newPowerSink.getAttachedStage();

            initStage.addPwrSink(newPowerSink);

            stageStates = lvdData.initStateModel.stageStates;
            stageStateInd = find([stageStates.stage] == initStage,1,'first');
            stageState = stageStates(stageStateInd);

            newSinkState = newPowerSink.createDefaultInitialState(stageState);
            stageState.addPowerSinkState(newSinkState);

            [~, ~] = app.setListboxStr();
        end

        % Window key press function: lvd_EditElectricalPowerSinksGUI
        function lvd_EditElectricalPowerSinksGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditElectricalPowerSinksGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            switch(eventdata.Key)
                case 'return'
                    close(handles.lvd_EditElectricalPowerSinksGUI);
                case 'enter'
                    close(handles.lvd_EditElectricalPowerSinksGUI);
                case 'escape'
                    close(handles.lvd_EditElectricalPowerSinksGUI);
            end
        end

        % Button pushed function: removeSinkButton
        function removeSinkButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeSinkButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditElectricalPowerSinksGUI,'lvdData');
%             lv = lvdData.launchVehicle;

            powerSink = app.sinkListBox.Value;

            tf = powerSink.isInUse();

            if(tf == false)
                stage = powerSink.getAttachedStage();

                stageStates = lvdData.initStateModel.stageStates;
                stageStateInd = find([stageStates.stage] == stage,1,'first');
                stageState = stageStates(stageStateInd);

                stageState.removePowerSinkStateForPowerState(powerSink);

                stage.removePwrSink(powerSink);

                [~, ~] = app.setListboxStr();
            else
                msg = sprintf('Could not delete the electical power sink "%s" because it is in use as part of an event termination condition, event action, objective function, or constraint.  Remove the dependencies before attempting to delete the electical power sink.', powerSink.getName());
%                 warndlg(msg,'Cannot Delete Power Sink','modal');
                uialert(app.lvd_EditElectricalPowerSinksGUI, msg, 'Cannot Delete Power Sink',"Modal",true);
            end
        end

        % Value changed function: sinkListBox
        function sinkListBox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to sinkListBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns sinkListBox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from sinkListBox
%             if(strcmpi(get(handles.lvd_EditElectricalPowerSinksGUI,'SelectionType'),'open'))
%                 lvdData = getappdata(handles.lvd_EditElectricalPowerSinksGUI,'lvdData');
%                 lv = lvdData.launchVehicle;
% 
%                 selPowerSink = get(handles.sinkListBox,'Value');
%                 powerSink = lv.getPowerSinkForInd(selPowerSink);
% 
%                 stageState = getStageStateForPowerState(app, powerSink, lvdData);
%                 oldSinkState = stageState.getStateForPowerSink(powerSink);
%                 stageState.removePowerSinkStateForPowerState(powerSink);
% 
%                 powerSink.openEditDialog();
% 
%                 stageState = getStageStateForPowerState(app, powerSink, lvdData);
%                 newPowerSinkState = powerSink.createDefaultInitialState(stageState);
% 
%                 if(not(isempty(oldSinkState)))
%                     newPowerSinkState.setActiveState(oldSinkState.getActiveState());
%                 end
%                 stageState.addPowerSinkState(newPowerSinkState);
% 
%                 [~, ~] = app.setListboxStr();
%             end
        end

        % Window button down function: lvd_EditElectricalPowerSinksGUI
        function lvd_EditElectricalPowerSinksGUIWindowButtonDown(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            selType = app.lvd_EditElectricalPowerSinksGUI.SelectionType;
            curObj = app.lvd_EditElectricalPowerSinksGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.sinkListBox)
                    lvdData = getappdata(handles.lvd_EditElectricalPowerSinksGUI,'lvdData');
%                     lv = lvdData.launchVehicle;
    
                    powerSink = app.sinkListBox.Value;
    
                    stageState = getStageStateForPowerState(app, powerSink, lvdData);
                    oldSinkState = stageState.getStateForPowerSink(powerSink);
                    stageState.removePowerSinkStateForPowerState(powerSink);
    
                    powerSink.openEditDialog();
    
                    stageState = getStageStateForPowerState(app, powerSink, lvdData);
                    newPowerSinkState = powerSink.createDefaultInitialState(stageState);
    
                    if(not(isempty(oldSinkState)))
                        newPowerSinkState.setActiveState(oldSinkState.getActiveState());
                    end
                    stageState.addPowerSinkState(newPowerSinkState);
    
                    [~, ~] = app.setListboxStr();
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditElectricalPowerSinksGUI = uifigure('Visible', 'off');
            app.lvd_EditElectricalPowerSinksGUI.Name = 'Edit Electrical Power Sinks';
            app.lvd_EditElectricalPowerSinksGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditElectricalPowerSinksGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_EditElectricalPowerSinksGUIWindowButtonDown, true);
            app.lvd_EditElectricalPowerSinksGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditElectricalPowerSinksGUI_WindowKeyPressFcn, true);
            app.lvd_EditElectricalPowerSinksGUI.Tag = 'lvd_EditElectricalPowerSinksGUI';
            app.GridLayout = uigridlayout(app.lvd_EditElectricalPowerSinksGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Electrical Power Sinks';
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Icon = 'close_window(1).png';
            app.closeButton.Text = 'Close';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.sinkListBox = uilistbox(app.GridLayout2);
            app.sinkListBox.Items = {'Listbox'};
            app.sinkListBox.ValueChangedFcn = createCallbackFcn(app, @sinkListBox_Callback, true);
            app.sinkListBox.Tag = 'sinkListBox';
            app.sinkListBox.Value = 'Listbox';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.addSinkButton = uibutton(app.GridLayout3, 'push');
            app.addSinkButton.ButtonPushedFcn = createCallbackFcn(app, @addSinkButton_Callback, true);
            app.addSinkButton.Tag = 'addSinkButton';
            app.addSinkButton.Icon = 'add.png';
            app.addSinkButton.Text = 'Add Power Sink';
            app.removeSinkButton = uibutton(app.GridLayout3, 'push');
            app.removeSinkButton.ButtonPushedFcn = createCallbackFcn(app, @removeSinkButton_Callback, true);
            app.removeSinkButton.Tag = 'removeSinkButton';
            app.removeSinkButton.Icon = 'remove.png';
            app.removeSinkButton.Text = 'Remove Power Sink';
            app.sinksListboxContextMenu = uicontextmenu(app.lvd_EditElectricalPowerSinksGUI);
            app.sinksListboxContextMenu.Tag = 'sinksListboxContextMenu';
            app.copySinkMenu = uimenu(app.sinksListboxContextMenu);
            app.copySinkMenu.MenuSelectedFcn = createCallbackFcn(app, @copySinkMenu_Callback, true);
            app.copySinkMenu.Text = 'Create Copy of Selected Sink';
            app.copySinkMenu.Tag = 'copySinkMenu';
            % Assign app.sinksListboxContextMenu
            app.sinkListBox.ContextMenu = app.sinksListboxContextMenu;
            % Show the figure after all components are created
            app.lvd_EditElectricalPowerSinksGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditElectricalPowerSinksGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditElectricalPowerSinksGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditElectricalPowerSinksGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditElectricalPowerSinksGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditElectricalPowerSinksGUI)
        end
    end
end