classdef lvd_EditSimplePowerSinkGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditSimplePowerSinkGUI  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        uipanel1                    matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        GridLayout4                 matlab.ui.container.GridLayout
        dischargeRateText           matlab.ui.control.EditField
        text6                       matlab.ui.control.Label
        stageCombo                  matlab.ui.control.DropDown
        sinkNameText                matlab.ui.control.EditField
        text5                       matlab.ui.control.Label
        text13                      matlab.ui.control.Label
        stageName                   matlab.ui.control.Label
        GridLayout2                 matlab.ui.container.GridLayout
        cancelButton                matlab.ui.control.Button
        saveAndCloseButton          matlab.ui.control.Button
        text2                       matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, powerSink)
            lv = powerSink.getAttachedStage().launchVehicle;
            [stagesListboxStr, ~] = lv.getStagesListBoxStr();
            ind = lv.getIndOfStage(powerSink.stage);

            if(isempty(ind))
                ind = 1;
            end

            set(handles.sinkNameText,'String',powerSink.getName());

            set(handles.stageCombo,'String',stagesListboxStr);
            set(handles.stageCombo,'Value',ind);

            set(handles.dischargeRateText,'String',fullAccNum2Str(-1*powerSink.pwrRate));
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            val = str2double(get(handles.dischargeRateText,'String'));
            enteredStr = get(handles.dischargeRateText,'String');
            numberName = 'Discharge Rate';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
        end

        
        function saveAndClose(app, handles)
            powerSink = getappdata(handles.lvd_EditSimplePowerSinkGUI, 'powerSink');
            lv = powerSink.getAttachedStage().launchVehicle;
            
            stage = lv.getStageForInd(handles.stageCombo.Value);
            
            name = handles.sinkNameText.String;
            dischargeRate = -1*str2double(handles.dischargeRateText.String);
            
            powerSink.name = name;
            powerSink.stage = stage;
            powerSink.pwrRate = dischargeRate;
            
            lv.movePwrSinkToStage(powerSink, stage);
                    
            app.output.output = {true};
            close(handles.lvd_EditSimplePowerSinkGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditSimplePowerSinkGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditSimplePowerSinkGUI (see VARARGIN)

            % Choose default command line output for lvd_EditSimplePowerSinkGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            powerSink = varargin{1};
            setappdata(hObject, 'powerSink', powerSink);
            
            app.output = varargin{2};

            populateGUI(app, handles, powerSink);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditSimplePowerSinkGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditSimplePowerSinkGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditSimplePowerSinkGUI);
        end

        % Value changed function: dischargeRateText
        function dischargeRateText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to dischargeRateText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of dischargeRateText as text
            %        str2double(get(hObject,'String')) returns contents of dischargeRateText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Window key press function: lvd_EditSimplePowerSinkGUI
        function lvd_EditSimplePowerSinkGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditSimplePowerSinkGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditSimplePowerSinkGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
%                 uiresume(handles.lvd_EditSimplePowerSinkGUI);
                app.saveAndClose(handles);
            else
%                 msgbox(errMsg,'Invalid Power Sink Inputs','error');
                uialert(app.lvd_EditSimplePowerSinkGUI, errMsg, 'Invalid Power Sink Inputs', "Icon","error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditSimplePowerSinkGUI = uifigure('Visible', 'off');
            app.lvd_EditSimplePowerSinkGUI.Name = 'Edit Simple Power Sink';
            app.lvd_EditSimplePowerSinkGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditSimplePowerSinkGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditSimplePowerSinkGUI_WindowKeyPressFcn, true);
            app.lvd_EditSimplePowerSinkGUI.Tag = 'lvd_EditSimplePowerSinkGUI';
            app.GridLayout = uigridlayout(app.lvd_EditSimplePowerSinkGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Simple Power Sink';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.stageName = uilabel(app.GridLayout3);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Power Sink Name';
            app.text13 = uilabel(app.GridLayout3);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Assigned Stage';
            app.text5 = uilabel(app.GridLayout3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Discharge Rate';
            app.sinkNameText = uieditfield(app.GridLayout3, 'text');
            app.sinkNameText.Tag = 'sinkNameText';
            app.sinkNameText.Value = 'ENGINE NAME';
            app.stageCombo = uidropdown(app.GridLayout3);
            app.stageCombo.Items = {'Pop-up Menu'};
            app.stageCombo.Tag = 'stageCombo';
            app.stageCombo.Value = 'Pop-up Menu';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.text6 = uilabel(app.GridLayout4);
            app.text6.Tag = 'text6';
            app.text6.Text = 'EC/s';
            app.dischargeRateText = uieditfield(app.GridLayout4, 'text');
            app.dischargeRateText.ValueChangedFcn = createCallbackFcn(app, @dischargeRateText_Callback, true);
            app.dischargeRateText.Tag = 'dischargeRateText';
            app.dischargeRateText.Tooltip = 'The constant rate at which this sink pulls EC from the vehicle batteries.';
            app.dischargeRateText.Value = '1';
            % Show the figure after all components are created
            app.lvd_EditSimplePowerSinkGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditSimplePowerSinkGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditSimplePowerSinkGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditSimplePowerSinkGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditSimplePowerSinkGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditSimplePowerSinkGUI)
        end
    end
end