classdef lvd_EditElectricalPowerSrcsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditElectricalPowerSrcsGUI  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        removeSrcButton                 matlab.ui.control.Button
        addSrcButton                    matlab.ui.control.Button
        sourcesListBox                  matlab.ui.control.ListBox
        closeButton                     matlab.ui.control.Button
        text2                           matlab.ui.control.Label
        srcsListboxContextMenu          matlab.ui.container.ContextMenu
        copySrcMenu                     matlab.ui.container.Menu
    end


    methods (Access = private)
        function stageState = getStageStateForPowerSrcState(app, powerSrc, lvdData)
            stage = powerSrc.stage;
            stageStates = lvdData.initStateModel.stageStates;
            stageStateInd = find([stageStates.stage] == stage,1,'first');
            stageState = stageStates(stageStateInd);
        end

        function populateGUI(app, handles, lvdData)
            [~, ~] = app.setListboxStr();
        end

        function [powerSrcListStr, powerSrcs] = setListboxStr(app)
            lvdData = getappdata(app.lvd_EditElectricalPowerSrcsGUI,'lvdData');
            [powerSrcListStr, powerSrcs] = lvdData.launchVehicle.getPowerSrcsListBoxStr();
            app.sourcesListBox.Items = powerSrcListStr;
            app.sourcesListBox.ItemsData = powerSrcs;
            
            num = numel(powerSrcs);
            if(num <= 0)
                app.removeSrcButton.Enable = 'off';
            else
                app.removeSrcButton.Enable = 'on';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditElectricalPowerSrcsGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditElectricalPowerSrcsGUI (see VARARGIN)

            % Choose default command line output for lvd_EditElectricalPowerSrcsGUI
            handles.output = hObject;
            
            centerUIFigure(app.lvd_EditElectricalPowerSrcsGUI);
            applySelectedThemeToApp(app);

            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            populateGUI(app, handles, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditElectricalPowerSrcsGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditElectricalPowerSrcsGUI);
        end

        % Button pushed function: addSrcButton
        function addSrcButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addSrcButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditElectricalPowerSrcsGUI,'lvdData');

            srcListBoxStr = LaunchVehiclePowerSrcEnum.getListBoxStr();
            out = AppDesignerGUIOutput();
            listdlgARH_App('ListString', srcListBoxStr, ...
                            'SelectionMode','single', ...
                            'Name', 'New Power Source Type', ...
                            'PromptString','Select type of new power source:', ...
                            'ListSize',[300,300], ...
                            'out',out);
            Selection = out.output{1};
            ok = out.output{2};

            if(ok == 1)
                initStage = lvdData.launchVehicle.getStageForInd(1);

                srcEnum = LaunchVehiclePowerSrcEnum.getEnumForListboxStr(srcListBoxStr{Selection});
                switch srcEnum
                    case LaunchVehiclePowerSrcEnum.RTG
                        powerSrc = LaunchVehicleEpsRtg(initStage);

                    case LaunchVehiclePowerSrcEnum.FixedSolarPanel
                        powerSrc = LaunchVehicleStaticSolarPanel(initStage);

                    case LaunchVehiclePowerSrcEnum.RotatingSolarPanel
                        powerSrc = LaunchVehicleRotatingSolarPanel(initStage);

                    otherwise
                        error('Unknown Power Source Type: %s', class(srcEnum))
                end

                useTF = powerSrc.openEditDialog();

                if(useTF)
                    powerSrc.getAttachedStage().addPwrSrc(powerSrc);
                    
                    stageStates = lvdData.initStateModel.stageStates;
                    stageStateInd = find([stageStates.stage] == powerSrc.stage,1,'first');
                    stageState = stageStates(stageStateInd);

                    newSrcState = powerSrc.createDefaultInitialState(stageState);
                    stageState.addPowerSrcState(newSrcState);

                     [~, ~] = app.setListboxStr();
                end
            end
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to closeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditElectricalPowerSrcsGUI);
        end

        % Menu selected function: copySrcMenu
        function copySrcMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to copySrcMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditElectricalPowerSrcsGUI,'lvdData');
%             lv = lvdData.launchVehicle;

            powerSrc = app.sourcesListBox.Value;

            newPowerSrc = powerSrc.copy();
            initStage = newPowerSrc.getAttachedStage();

            initStage.addPwrSrc(newPowerSrc);

            stageStates = lvdData.initStateModel.stageStates;
            stageStateInd = find([stageStates.stage] == initStage,1,'first');
            stageState = stageStates(stageStateInd);

            newSinkState = newPowerSrc.createDefaultInitialState(stageState);
            stageState.addPowerSrcState(newSinkState);

            [~, ~] = app.setListboxStr();
        end

        % Window key press function: lvd_EditElectricalPowerSrcsGUI
        function lvd_EditElectricalPowerSrcsGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditElectricalPowerSrcsGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            switch(eventdata.Key)
                case 'return'
                    close(handles.lvd_EditElectricalPowerSrcsGUI);
                case 'enter'
                    close(handles.lvd_EditElectricalPowerSrcsGUI);
                case 'escape'
                    close(handles.lvd_EditElectricalPowerSrcsGUI);
            end
        end

        % Button pushed function: removeSrcButton
        function removeSrcButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeSrcButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditElectricalPowerSrcsGUI,'lvdData');
%             lv = lvdData.launchVehicle;

            powerSrc = app.sourcesListBox.Value;

            tf = powerSrc.isInUse();

            if(tf == false)
                stage = powerSrc.getAttachedStage();

                stageStates = lvdData.initStateModel.stageStates;
                stageStateInd = find([stageStates.stage] == stage,1,'first');
                stageState = stageStates(stageStateInd);

                stageState.removePowerSrcStateForPowerState(powerSrc);

                stage.removePwrSrc(powerSrc);

                 [~, ~] = app.setListboxStr();
            else
                msg = sprintf('Could not delete the electical power source "%s" because it is in use as part of an event termination condition, event action, objective function, or constraint.  Remove the dependencies before attempting to delete the electical power source.', powerSrc.getName());
%                 warndlg(msg,'Cannot Delete Power Source','modal');
                uialert(app.lvd_EditElectricalPowerSrcsGUI, msg, 'Cannot Delete Power Source',"Modal",true);
            end
        end

        % Value changed function: sourcesListBox
        function sourcesListBox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to sourcesListBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns sourcesListBox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from sourcesListBox
%             if(strcmpi(get(handles.lvd_EditElectricalPowerSrcsGUI,'SelectionType'),'open'))
%                 lvdData = getappdata(handles.lvd_EditElectricalPowerSrcsGUI,'lvdData');
% %                 lv = lvdData.launchVehicle;
% 
%                 powerSrc = app.sourcesListBox.Value;
% 
%                 stageState = getStageStateForPowerSrcState(app, powerSrc, lvdData);
%                 oldSrcState = stageState.getStateForPowerSrc(powerSrc);
%                 stageState.removePowerSrcStateForPowerState(powerSrc);
% 
%                 powerSrc.openEditDialog();
% 
%                 stageState = getStageStateForPowerSrcState(app, powerSrc, lvdData);
%                 newPowerSrcState = powerSrc.createDefaultInitialState(stageState);
% 
%                 if(not(isempty(oldSrcState)))
%                     newPowerSrcState.setActiveState(oldSrcState.getActiveState());
%                 end
%                 stageState.addPowerSrcState(newPowerSrcState);
% 
%                  [~, ~] = app.setListboxStr();
%             end
        end

        % Window button down function: lvd_EditElectricalPowerSrcsGUI
        function lvd_EditElectricalPowerSrcsGUIWindowButtonDown(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            selType = app.lvd_EditElectricalPowerSrcsGUI.SelectionType;
            curObj = app.lvd_EditElectricalPowerSrcsGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.sourcesListBox)
                    lvdData = getappdata(handles.lvd_EditElectricalPowerSrcsGUI,'lvdData');
    
                    powerSrc = app.sourcesListBox.Value;
    
                    stageState = getStageStateForPowerSrcState(app, powerSrc, lvdData);
                    oldSrcState = stageState.getStateForPowerSrc(powerSrc);
                    stageState.removePowerSrcStateForPowerState(powerSrc);
    
                    powerSrc.openEditDialog();
    
                    stageState = getStageStateForPowerSrcState(app, powerSrc, lvdData);
                    newPowerSrcState = powerSrc.createDefaultInitialState(stageState);
    
                    if(not(isempty(oldSrcState)))
                        newPowerSrcState.setActiveState(oldSrcState.getActiveState());
                    end
                    stageState.addPowerSrcState(newPowerSrcState);
    
                     [~, ~] = app.setListboxStr();
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditElectricalPowerSrcsGUI = uifigure('Visible', 'off');
            app.lvd_EditElectricalPowerSrcsGUI.Name = 'Edit Electrical Power Sources';
            app.lvd_EditElectricalPowerSrcsGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditElectricalPowerSrcsGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_EditElectricalPowerSrcsGUIWindowButtonDown, true);
            app.lvd_EditElectricalPowerSrcsGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditElectricalPowerSrcsGUI_WindowKeyPressFcn, true);
            app.lvd_EditElectricalPowerSrcsGUI.Tag = 'lvd_EditElectricalPowerSrcsGUI';
            app.GridLayout = uigridlayout(app.lvd_EditElectricalPowerSrcsGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Electrical Power Sources';
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Icon = 'close_window(1).png';
            app.closeButton.Text = 'Close';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.sourcesListBox = uilistbox(app.GridLayout2);
            app.sourcesListBox.Items = {'Listbox'};
            app.sourcesListBox.ValueChangedFcn = createCallbackFcn(app, @sourcesListBox_Callback, true);
            app.sourcesListBox.Tag = 'sourcesListBox';
            app.sourcesListBox.Value = 'Listbox';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.addSrcButton = uibutton(app.GridLayout3, 'push');
            app.addSrcButton.ButtonPushedFcn = createCallbackFcn(app, @addSrcButton_Callback, true);
            app.addSrcButton.Tag = 'addSrcButton';
            app.addSrcButton.Icon = 'add.png';
            app.addSrcButton.Text = 'Add Power Source';
            app.removeSrcButton = uibutton(app.GridLayout3, 'push');
            app.removeSrcButton.ButtonPushedFcn = createCallbackFcn(app, @removeSrcButton_Callback, true);
            app.removeSrcButton.Tag = 'removeSrcButton';
            app.removeSrcButton.Icon = 'remove.png';
            app.removeSrcButton.Tooltip = 'Remove currently selected power source.';
            app.removeSrcButton.Text = 'Remove Power Source';
            app.srcsListboxContextMenu = uicontextmenu(app.lvd_EditElectricalPowerSrcsGUI);
            app.srcsListboxContextMenu.Tag = 'srcsListboxContextMenu';
            app.copySrcMenu = uimenu(app.srcsListboxContextMenu);
            app.copySrcMenu.MenuSelectedFcn = createCallbackFcn(app, @copySrcMenu_Callback, true);
            app.copySrcMenu.Text = 'Create Copy of Selected Power Source';
            app.copySrcMenu.Tag = 'copySrcMenu';
            % Assign app.srcsListboxContextMenu
            app.sourcesListBox.ContextMenu = app.srcsListboxContextMenu;
            % Show the figure after all components are created
            app.lvd_EditElectricalPowerSrcsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditElectricalPowerSrcsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditElectricalPowerSrcsGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditElectricalPowerSrcsGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditElectricalPowerSrcsGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditElectricalPowerSrcsGUI)
        end
    end
end