classdef lvd_EditFixedSolarPanelGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditFixedSolarPanelGUI  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        uipanel1                    matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        SolarPanelBodyFixedNormalUnitVector  matlab.ui.container.Panel
        GridLayout8                 matlab.ui.container.GridLayout
        text6_4                     matlab.ui.control.Label
        text6_3                     matlab.ui.control.Label
        text6_2                     matlab.ui.control.Label
        panelZText                  matlab.ui.control.EditField
        panelYText                  matlab.ui.control.EditField
        panelXText                  matlab.ui.control.EditField
        text24                      matlab.ui.control.Label
        text23                      matlab.ui.control.Label
        text22                      matlab.ui.control.Label
        GridLayout7                 matlab.ui.container.GridLayout
        text19                      matlab.ui.control.Label
        refDistText                 matlab.ui.control.EditField
        text18                      matlab.ui.control.Label
        GridLayout6                 matlab.ui.container.GridLayout
        text6                       matlab.ui.control.Label
        refChargeRateText           matlab.ui.control.EditField
        text5                       matlab.ui.control.Label
        GridLayout5                 matlab.ui.container.GridLayout
        stageCombo                  matlab.ui.control.DropDown
        text13                      matlab.ui.control.Label
        GridLayout4                 matlab.ui.container.GridLayout
        solarPanelNameText          matlab.ui.control.EditField
        stageName                   matlab.ui.control.Label
        GridLayout2                 matlab.ui.container.GridLayout
        cancelButton                matlab.ui.control.Button
        saveAndCloseButton          matlab.ui.control.Button
        text2                       matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, fixedSolarPanel)
            lv = fixedSolarPanel.getAttachedStage().launchVehicle;
            [stagesListboxStr, ~] = lv.getStagesListBoxStr();
            ind = lv.getIndOfStage(fixedSolarPanel.stage);

            if(isempty(ind))
                ind = 1;
            end

            set(handles.solarPanelNameText,'String',fixedSolarPanel.getName());

            set(handles.stageCombo,'String',stagesListboxStr);
            set(handles.stageCombo,'Value',ind);

            set(handles.refChargeRateText,'String',fullAccNum2Str(fixedSolarPanel.refChargeRate));
            set(handles.refDistText,'String',fullAccNum2Str(fixedSolarPanel.refChargeRateDist));

            set(handles.panelXText,'String',fullAccNum2Str(fixedSolarPanel.bodyFrameNormVect(1)));
            set(handles.panelYText,'String',fullAccNum2Str(fixedSolarPanel.bodyFrameNormVect(2)));
            set(handles.panelZText,'String',fullAccNum2Str(fixedSolarPanel.bodyFrameNormVect(3)));
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            chargeRate = str2double(get(handles.refChargeRateText,'String'));
            enteredStr = get(handles.refChargeRateText,'String');
            numberName = 'Ref. Charge Rate';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(chargeRate, numberName, lb, ub, isInt, errMsg, enteredStr);

            dist = str2double(get(handles.refDistText,'String'));
            enteredStr = get(handles.refDistText,'String');
            numberName = 'Ref. Distance';
            lb = 0.001;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(dist, numberName, lb, ub, isInt, errMsg, enteredStr);

            xVect = str2double(get(handles.panelXText,'String'));
            enteredStr = get(handles.panelXText,'String');
            numberName = 'Panel Normal Vector X';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(xVect, numberName, lb, ub, isInt, errMsg, enteredStr);

            yVect = str2double(get(handles.panelYText,'String'));
            enteredStr = get(handles.panelYText,'String');
            numberName = 'Panel Normal Vector Y';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(yVect, numberName, lb, ub, isInt, errMsg, enteredStr);

            zVect = str2double(get(handles.panelZText,'String'));
            enteredStr = get(handles.panelZText,'String');
            numberName = 'SolarPanelBodyFixedNormalUnitVector Normal Vector Z';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(zVect, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                if(norm([xVect; yVect; zVect]) <= 0)
                    errMsg{end+1} = 'Magnitude of the solar panel normal vector must be greater than 0.0.';
                end
            end
        end


        function saveAndClose(app, handles)
            fixedSolarPanel = getappdata(handles.lvd_EditFixedSolarPanelGUI, 'fixedSolarPanel');
            lv = fixedSolarPanel.getAttachedStage().launchVehicle;

            stage = lv.getStageForInd(handles.stageCombo.Value);

            name = handles.solarPanelNameText.String;
            refChargeRate = str2double(handles.refChargeRateText.String);
            refDist = str2double(handles.refDistText.String);

            xVect = str2double(handles.panelXText.String);
            yVect = str2double(handles.panelYText.String);
            zVect = str2double(handles.panelZText.String);

            fixedSolarPanel.name = name;
            fixedSolarPanel.stage = stage;
            fixedSolarPanel.refChargeRate = refChargeRate;
            fixedSolarPanel.refChargeRateDist = refDist;
            fixedSolarPanel.bodyFrameNormVect = normVector([xVect; yVect; zVect]);

            lv.movePwrSrcToStage(fixedSolarPanel, stage);

            app.output.output = {true};
            close(handles.lvd_EditFixedSolarPanelGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditFixedSolarPanelGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditFixedSolarPanelGUI (see VARARGIN)

            % Choose default command line output for lvd_EditFixedSolarPanelGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            fixedSolarPanel = varargin{1};
            setappdata(hObject, 'fixedSolarPanel', fixedSolarPanel);

            app.output = varargin{2};

            populateGUI(app, handles, fixedSolarPanel);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditFixedSolarPanelGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditFixedSolarPanelGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditFixedSolarPanelGUI);
        end

        % Window key press function: lvd_EditFixedSolarPanelGUI
        function lvd_EditFixedSolarPanelGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditFixedSolarPanelGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditFixedSolarPanelGUI);
            end
        end

        % Value changed function: panelXText
        function panelXText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to panelXText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of panelXText as text
            %        str2double(get(hObject,'String')) returns contents of panelXText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: panelYText
        function panelYText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to panelYText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of panelYText as text
            %        str2double(get(hObject,'String')) returns contents of panelYText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: panelZText
        function panelZText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to panelZText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of panelZText as text
            %        str2double(get(hObject,'String')) returns contents of panelZText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: refChargeRateText
        function refChargeRateText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to refChargeRateText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of refChargeRateText as text
            %        str2double(get(hObject,'String')) returns contents of refChargeRateText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: refDistText
        function refDistText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to refDistText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of refDistText as text
            %        str2double(get(hObject,'String')) returns contents of refDistText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                %                     uiresume(handles.lvd_EditFixedSolarPanelGUI);
                app.saveAndClose(handles);
            else
                %                     msgbox(errMsg,'Invalid Solar Panel Inputs','error');
                uialert(app.lvd_EditFixedSolarPanelGUI, errMsg, 'Invalid Solar Panel Inputs', "Icon","error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditFixedSolarPanelGUI = uifigure('Visible', 'off');
            app.lvd_EditFixedSolarPanelGUI.Name = 'Edit Fixed Solar Panel';
            app.lvd_EditFixedSolarPanelGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditFixedSolarPanelGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditFixedSolarPanelGUI_WindowKeyPressFcn, true);
            app.lvd_EditFixedSolarPanelGUI.Tag = 'lvd_EditFixedSolarPanelGUI';
            app.GridLayout = uigridlayout(app.lvd_EditFixedSolarPanelGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Fixed Solar Panel';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.stageName = uilabel(app.GridLayout4);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Solar Panel Name';
            app.solarPanelNameText = uieditfield(app.GridLayout4, 'text');
            app.solarPanelNameText.Tag = 'solarPanelNameText';
            app.solarPanelNameText.Value = 'ENGINE NAME';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.text13 = uilabel(app.GridLayout5);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Assigned Stage';
            app.stageCombo = uidropdown(app.GridLayout5);
            app.stageCombo.Items = {'Pop-up Menu'};
            app.stageCombo.Tag = 'stageCombo';
            app.stageCombo.Value = 'Pop-up Menu';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.text5 = uilabel(app.GridLayout6);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Ref. Charge Rate';
            app.refChargeRateText = uieditfield(app.GridLayout6, 'text');
            app.refChargeRateText.ValueChangedFcn = createCallbackFcn(app, @refChargeRateText_Callback, true);
            app.refChargeRateText.Tag = 'refChargeRateText';
            app.refChargeRateText.Tooltip = 'The charge rate of this solar panel at the reference distance from the Sun.';
            app.refChargeRateText.Value = '1';
            app.text6 = uilabel(app.GridLayout6);
            app.text6.Tag = 'text6';
            app.text6.Text = 'EC/s';
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.text18 = uilabel(app.GridLayout7);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Ref. Distance';
            app.refDistText = uieditfield(app.GridLayout7, 'text');
            app.refDistText.ValueChangedFcn = createCallbackFcn(app, @refDistText_Callback, true);
            app.refDistText.Tag = 'refDistText';
            app.refDistText.Tooltip = 'The distance from the Sun at which the panel produces the reference charge rate.  In stock KSP, this is generally Kerbin''s SMA.';
            app.refDistText.Value = '1';
            app.text19 = uilabel(app.GridLayout7);
            app.text19.Tag = 'text19';
            app.text19.Text = 'km';
            app.SolarPanelBodyFixedNormalUnitVector = uipanel(app.GridLayout3);
            app.SolarPanelBodyFixedNormalUnitVector.Title = 'Solar Panel Body-Fixed Normal Unit Vector';
            app.GridLayout8 = uigridlayout(app.SolarPanelBodyFixedNormalUnitVector);
            app.text22 = uilabel(app.GridLayout8);
            app.text22.Tag = 'text22';
            app.text22.Text = 'X';
            app.text23 = uilabel(app.GridLayout8);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Y';
            app.text24 = uilabel(app.GridLayout8);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Z';
            app.panelXText = uieditfield(app.GridLayout8, 'text');
            app.panelXText.ValueChangedFcn = createCallbackFcn(app, @panelXText_Callback, true);
            app.panelXText.Tag = 'panelXText';
            app.panelXText.Tooltip = 'The X component of the solar panel''s normal vector in the spacecraft''s body frame.  ';
            app.panelXText.Value = '1';
            app.panelYText = uieditfield(app.GridLayout8, 'text');
            app.panelYText.ValueChangedFcn = createCallbackFcn(app, @panelYText_Callback, true);
            app.panelYText.Tag = 'panelYText';
            app.panelYText.Tooltip = 'The Y component of the solar panel''s normal vector in the spacecraft''s body frame.  ';
            app.panelYText.Value = '1';
            app.panelZText = uieditfield(app.GridLayout8, 'text');
            app.panelZText.ValueChangedFcn = createCallbackFcn(app, @panelZText_Callback, true);
            app.panelZText.Tag = 'panelZText';
            app.panelZText.Tooltip = 'The Z component of the solar panel''s normal vector in the spacecraft''s body frame. ';
            app.panelZText.Value = '1';
            app.text6_2 = uilabel(app.GridLayout8);
            app.text6_2.Tag = 'text6';
            app.text6_2.Text = '';
            app.text6_3 = uilabel(app.GridLayout8);
            app.text6_3.Tag = 'text6';
            app.text6_3.Text = '';
            app.text6_4 = uilabel(app.GridLayout8);
            app.text6_4.Tag = 'text6';
            app.text6_4.Text = '';
            % Show the figure after all components are created
            app.lvd_EditFixedSolarPanelGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditFixedSolarPanelGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditFixedSolarPanelGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditFixedSolarPanelGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditFixedSolarPanelGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditFixedSolarPanelGUI)
        end
    end
end