classdef lvd_EditPowerRtgGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditPowerRtgGUI    matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        uipanel1               matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        GridLayout6            matlab.ui.container.GridLayout
        decayInitTimeText      matlab.ui.control.EditField
        text21                 matlab.ui.control.Label
        GridLayout5            matlab.ui.container.GridLayout
        halfLifeText           matlab.ui.control.EditField
        text19                 matlab.ui.control.Label
        GridLayout4            matlab.ui.container.GridLayout
        chargeRateText         matlab.ui.control.EditField
        text6                  matlab.ui.control.Label
        stageCombo             matlab.ui.control.DropDown
        rtgNameText            matlab.ui.control.EditField
        text20                 matlab.ui.control.Label
        text18                 matlab.ui.control.Label
        text5                  matlab.ui.control.Label
        text13                 matlab.ui.control.Label
        stageName              matlab.ui.control.Label
        GridLayout2            matlab.ui.container.GridLayout
        cancelButton           matlab.ui.control.Button
        saveAndCloseButton     matlab.ui.control.Button
        text2                  matlab.ui.control.Label
        InitialTimeMenu        matlab.ui.container.ContextMenu
        enterUtAsDateTimeMenu  matlab.ui.container.Menu
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, rtg)
            lv = rtg.getAttachedStage().launchVehicle;
            [stagesListboxStr, ~] = lv.getStagesListBoxStr();
            ind = lv.getIndOfStage(rtg.stage);

            if(isempty(ind))
                ind = 1;
            end

            set(handles.rtgNameText,'String',rtg.getName());

            set(handles.stageCombo,'String',stagesListboxStr);
            set(handles.stageCombo,'Value',ind);

            set(handles.chargeRateText,'String',fullAccNum2Str(rtg.initPwrRate));
            set(handles.halfLifeText,'String',fullAccNum2Str(rtg.halfLife));
            set(handles.decayInitTimeText,'String',fullAccNum2Str(rtg.initTime));
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            val = str2double(get(handles.chargeRateText,'String'));
            enteredStr = get(handles.chargeRateText,'String');
            numberName = 'Initial Charge Rate';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);

            val = str2double(get(handles.halfLifeText,'String'));
            enteredStr = get(handles.halfLifeText,'String');
            numberName = 'Half Life';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);

            val = str2double(get(handles.decayInitTimeText,'String'));
            enteredStr = get(handles.decayInitTimeText,'String');
            numberName = 'Decay Initial Time';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
        end


        function saveAndClose(app, handles)
            rtg = getappdata(handles.lvd_EditPowerRtgGUI, 'rtg');
            lv = rtg.getAttachedStage().launchVehicle;

            stage = lv.getStageForInd(handles.stageCombo.Value);

            name = handles.rtgNameText.String;
            initChargeRate = str2double(handles.chargeRateText.String);
            halfLife = str2double(handles.halfLifeText.String);
            decayInitTime = str2double(handles.decayInitTimeText.String);

            rtg.name = name;
            rtg.stage = stage;
            rtg.initPwrRate = initChargeRate;
            rtg.halfLife = halfLife;
            rtg.initTime = decayInitTime;

            lv.movePwrSrcToStage(rtg, stage);

            app.output.output = {true};
            close(handles.lvd_EditPowerRtgGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditPowerRtgGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditPowerRtgGUI (see VARARGIN)

            % Choose default command line output for lvd_EditPowerRtgGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            rtg = varargin{1};
            setappdata(hObject, 'rtg', rtg);
            
            app.output = varargin{2};

            populateGUI(app, handles, rtg);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditPowerRtgGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditPowerRtgGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditPowerRtgGUI);
        end

        % Value changed function: chargeRateText
        function chargeRateText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to chargeRateText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of chargeRateText as text
            %        str2double(get(hObject,'String')) returns contents of chargeRateText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: decayInitTimeText
        function decayInitTimeText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.decayInitTimeText;
            
            % hObject    handle to decayInitTimeText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of decayInitTimeText as text
            %        str2double(get(hObject,'String')) returns contents of decayInitTimeText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Menu selected function: enterUtAsDateTimeMenu
        function enterUtAsDateTimeMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to enterUtAsDateTimeMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
%             secUT = enterUTAsDateTimeGUI(str2double(get(gco, 'String')));

            output2 = AppDesignerGUIOutput({-1});
            enterUTAsDateTimeGUI_App(str2double(get(gco, 'Value')), output2);
            secUT = output2.output{1};
            
            if(secUT >= 0)
                set(gco, 'String', num2str(secUT));
                
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                decayInitTimeText_Callback(app, evt);
            end
        end

        % Value changed function: halfLifeText
        function halfLifeText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to halfLifeText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of halfLifeText as text
            %        str2double(get(hObject,'String')) returns contents of halfLifeText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Window key press function: lvd_EditPowerRtgGUI
        function lvd_EditPowerRtgGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditPowerRtgGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditPowerRtgGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                %                     uiresume(handles.lvd_EditPowerRtgGUI);
                app.saveAndClose(handles);
            else
                %                     msgbox(errMsg,'Invalid RTG Inputs','error');
                uialert(app.lvd_EditPowerRtgGUI, errMsg, 'Invalid RTG Inputs', "Icon","error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditPowerRtgGUI = uifigure('Visible', 'off');
            app.lvd_EditPowerRtgGUI.Name = 'Edit RTG';
            app.lvd_EditPowerRtgGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditPowerRtgGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditPowerRtgGUI_WindowKeyPressFcn, true);
            app.lvd_EditPowerRtgGUI.Tag = 'lvd_EditPowerRtgGUI';
            app.GridLayout = uigridlayout(app.lvd_EditPowerRtgGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit RTG';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.stageName = uilabel(app.GridLayout3);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'RTG Name';
            app.text13 = uilabel(app.GridLayout3);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Assigned Stage';
            app.text5 = uilabel(app.GridLayout3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Initial Charge Rate';
            app.text18 = uilabel(app.GridLayout3);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Half Life';
            app.text20 = uilabel(app.GridLayout3);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Decay Initial Time';
            app.rtgNameText = uieditfield(app.GridLayout3, 'text');
            app.rtgNameText.Tag = 'rtgNameText';
            app.rtgNameText.Value = 'ENGINE NAME';
            app.stageCombo = uidropdown(app.GridLayout3);
            app.stageCombo.Items = {'Pop-up Menu'};
            app.stageCombo.Tag = 'stageCombo';
            app.stageCombo.Value = 'Pop-up Menu';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.text6 = uilabel(app.GridLayout4);
            app.text6.Tag = 'text6';
            app.text6.Text = 'EC/s';
            app.chargeRateText = uieditfield(app.GridLayout4, 'text');
            app.chargeRateText.ValueChangedFcn = createCallbackFcn(app, @chargeRateText_Callback, true);
            app.chargeRateText.Tag = 'chargeRateText';
            app.chargeRateText.Tooltip = 'The constant rate at which this RTG provides electrical power to the vehicle''s batteries.';
            app.chargeRateText.Value = '1';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.text19 = uilabel(app.GridLayout5);
            app.text19.Tag = 'text19';
            app.text19.Text = 'sec';
            app.halfLifeText = uieditfield(app.GridLayout5, 'text');
            app.halfLifeText.ValueChangedFcn = createCallbackFcn(app, @halfLifeText_Callback, true);
            app.halfLifeText.Tag = 'halfLifeText';
            app.halfLifeText.Tooltip = 'The half life of the RTG represents how long it takes for the RTG to go from producing 100% power to 50% power.  Set to 0 to disable RTG power decay.';
            app.halfLifeText.Value = '1';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.text21 = uilabel(app.GridLayout6);
            app.text21.Tag = 'text21';
            app.text21.Text = 'sec';
            app.decayInitTimeText = uieditfield(app.GridLayout6, 'text');
            app.decayInitTimeText.ValueChangedFcn = createCallbackFcn(app, @decayInitTimeText_Callback, true);
            app.decayInitTimeText.Tag = 'decayInitTimeText';
            app.decayInitTimeText.Tooltip = 'The universal time (UT) at which the RTG begins decaying.  If the current simulation time is before this time, the charge rate is set to the initial charge rate.';
            app.decayInitTimeText.Value = '1';
            app.InitialTimeMenu = uicontextmenu(app.lvd_EditPowerRtgGUI);
            app.InitialTimeMenu.Tag = 'InitialTimeMenu';
            app.enterUtAsDateTimeMenu = uimenu(app.InitialTimeMenu);
            app.enterUtAsDateTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @enterUtAsDateTimeMenu_Callback, true);
            app.enterUtAsDateTimeMenu.Text = 'Enter UT As Date/Time';
            app.enterUtAsDateTimeMenu.Tag = 'enterUtAsDateTimeMenu';
            % Assign app.InitialTimeMenu
            app.decayInitTimeText.ContextMenu = app.InitialTimeMenu;
            % Show the figure after all components are created
            app.lvd_EditPowerRtgGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditPowerRtgGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditPowerRtgGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditPowerRtgGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditPowerRtgGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditPowerRtgGUI)
        end
    end
end