classdef lvd_EditBasicBatteryGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditBasicBatteryGUI  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        uipanel1                 matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        GridLayout5              matlab.ui.container.GridLayout
        initStateOfChargeText    matlab.ui.control.EditField
        text19                   matlab.ui.control.Label
        GridLayout4              matlab.ui.container.GridLayout
        maxCapacityText          matlab.ui.control.EditField
        text6                    matlab.ui.control.Label
        stageCombo               matlab.ui.control.DropDown
        batteryNameText          matlab.ui.control.EditField
        text18                   matlab.ui.control.Label
        text5                    matlab.ui.control.Label
        text13                   matlab.ui.control.Label
        stageName                matlab.ui.control.Label
        GridLayout2              matlab.ui.container.GridLayout
        cancelButton             matlab.ui.control.Button
        saveAndCloseButton       matlab.ui.control.Button
        text2                    matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, powerStorage)
            lv = powerStorage.getAttachedStage().launchVehicle;
            [stagesListboxStr, ~] = lv.getStagesListBoxStr();
            ind = lv.getIndOfStage(powerStorage.stage);

            if(isempty(ind))
                ind = 1;
            end

            set(handles.batteryNameText,'String',powerStorage.getName());

            set(handles.stageCombo,'String',stagesListboxStr);
            set(handles.stageCombo,'Value',ind);

            set(handles.maxCapacityText,'String',fullAccNum2Str(powerStorage.maxCapacity));
            set(handles.initStateOfChargeText,'String',fullAccNum2Str(powerStorage.initialStateOfCharge));
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            maxCapacity = str2double(get(handles.maxCapacityText,'String'));
            enteredStr = get(handles.maxCapacityText,'String');
            numberName = 'Maximum Capacity';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(maxCapacity, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                initSoC = str2double(get(handles.initStateOfChargeText,'String'));
                enteredStr = get(handles.initStateOfChargeText,'String');
                numberName = 'Initial State of Charge';
                lb = 0;
                ub = maxCapacity;
                isInt = false;
                errMsg = validateNumber(initSoC, numberName, lb, ub, isInt, errMsg, enteredStr);
            end
        end


        function saveAndClose(app, handles)
            powerStorage = getappdata(handles.lvd_EditBasicBatteryGUI, 'powerStorage');
            lv = powerStorage.getAttachedStage().launchVehicle;

            stage = lv.getStageForInd(handles.stageCombo.Value);

            name = handles.batteryNameText.String;
            maxCapacity = str2double(handles.maxCapacityText.String);
            initSoc = str2double(handles.initStateOfChargeText.String);

            powerStorage.name = name;
            powerStorage.stage = stage;
            powerStorage.maxCapacity = maxCapacity;
            powerStorage.initialStateOfCharge = initSoc;

            lv.movePwrStorageToStage(powerStorage, stage);

            app.output.output = {true};
            close(handles.lvd_EditBasicBatteryGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditBasicBatteryGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditBasicBatteryGUI (see VARARGIN)

            % Choose default command line output for lvd_EditBasicBatteryGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            powerStorage = varargin{1};
            setappdata(hObject, 'powerStorage', powerStorage);

            app.output = varargin{2};

            populateGUI(app, handles, powerStorage);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditBasicBatteryGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditBasicBatteryGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditBasicBatteryGUI);
        end

        % Value changed function: initStateOfChargeText
        function initStateOfChargeText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to initStateOfChargeText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of initStateOfChargeText as text
            %        str2double(get(hObject,'String')) returns contents of initStateOfChargeText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Window key press function: lvd_EditBasicBatteryGUI
        function lvd_EditBasicBatteryGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditBasicBatteryGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditBasicBatteryGUI);
            end
        end

        % Value changed function: maxCapacityText
        function maxCapacityText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to maxCapacityText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of maxCapacityText as text
            %        str2double(get(hObject,'String')) returns contents of maxCapacityText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                %                     uiresume(handles.lvd_EditBasicBatteryGUI);
                app.saveAndClose(handles);
            else
                %                     msgbox(errMsg,'Invalid Power Storage Inputs','error');
                uialert(app.lvd_EditBasicBatteryGUI, errMsg, 'Invalid Power Storage Inputs', "Icon","error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditBasicBatteryGUI = uifigure('Visible', 'off');
            app.lvd_EditBasicBatteryGUI.Name = 'Edit Basic Battery';
            app.lvd_EditBasicBatteryGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditBasicBatteryGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditBasicBatteryGUI_WindowKeyPressFcn, true);
            app.lvd_EditBasicBatteryGUI.Tag = 'lvd_EditBasicBatteryGUI';
            app.GridLayout = uigridlayout(app.lvd_EditBasicBatteryGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Basic Battery';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.stageName = uilabel(app.GridLayout3);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Battery Name';
            app.text13 = uilabel(app.GridLayout3);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Assigned Stage';
            app.text5 = uilabel(app.GridLayout3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Max Capacity';
            app.text18 = uilabel(app.GridLayout3);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Initial State of Chrg';
            app.batteryNameText = uieditfield(app.GridLayout3, 'text');
            app.batteryNameText.Tag = 'batteryNameText';
            app.batteryNameText.Value = 'ENGINE NAME';
            app.stageCombo = uidropdown(app.GridLayout3);
            app.stageCombo.Items = {'Pop-up Menu'};
            app.stageCombo.Tag = 'stageCombo';
            app.stageCombo.Value = 'Pop-up Menu';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.text6 = uilabel(app.GridLayout4);
            app.text6.Tag = 'text6';
            app.text6.Text = 'EC';
            app.maxCapacityText = uieditfield(app.GridLayout4, 'text');
            app.maxCapacityText.ValueChangedFcn = createCallbackFcn(app, @maxCapacityText_Callback, true);
            app.maxCapacityText.Tag = 'maxCapacityText';
            app.maxCapacityText.Tooltip = 'The maximum amount of electrical charge (EC) this battery can hold.';
            app.maxCapacityText.Value = '1';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.text19 = uilabel(app.GridLayout5);
            app.text19.Tag = 'text19';
            app.text19.Text = 'EC';
            app.initStateOfChargeText = uieditfield(app.GridLayout5, 'text');
            app.initStateOfChargeText.ValueChangedFcn = createCallbackFcn(app, @initStateOfChargeText_Callback, true);
            app.initStateOfChargeText.Tag = 'initStateOfChargeText';
            app.initStateOfChargeText.Tooltip = 'The initial state of charge of this battery.  Must be between 0.0 and the maximum capacity of the battery.';
            app.initStateOfChargeText.Value = '1';
            % Show the figure after all components are created
            app.lvd_EditBasicBatteryGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditBasicBatteryGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditBasicBatteryGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditBasicBatteryGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditBasicBatteryGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditBasicBatteryGUI)
        end
    end
end