classdef lvd_EditElectricalPowerStoragesGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditElectricalPowerStoragesGUI  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        Panel                      matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        GridLayout3                matlab.ui.container.GridLayout
        removeStorageButton        matlab.ui.control.Button
        addStorageButton           matlab.ui.control.Button
        storageListBox             matlab.ui.control.ListBox
        closeButton                matlab.ui.control.Button
        text2                      matlab.ui.control.Label
        storageListboxContextMenu  matlab.ui.container.ContextMenu
        copyStorageMenu            matlab.ui.container.Menu
    end


    methods (Access = private)
        function stageState = getStageStateForPowerState(app, powerStorage, lvdData)
            stage = powerStorage.getAttachedStage();
            stageStates = lvdData.initStateModel.stageStates;
            stageStateInd = find([stageStates.stage] == stage,1,'first');
            stageState = stageStates(stageStateInd);
        end

        function populateGUI(app, handles, lvdData)
            [~, ~] = app.setListboxStr();
        end

        function [powerStoragesListStr, powerStorages] = setListboxStr(app)
            lvdData = getappdata(app.lvd_EditElectricalPowerStoragesGUI,'lvdData');
            [powerStoragesListStr, powerStorages] = lvdData.launchVehicle.getPowerStoragesListBoxStr();
            app.storageListBox.Items = powerStoragesListStr;
            app.storageListBox.ItemsData = powerStorages;
            
            num = numel(powerStorages);
            if(num <= 0)
                app.removeStorageButton.Enable = 'off';
            else
                app.removeStorageButton.Enable = 'on';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditElectricalPowerStoragesGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditElectricalPowerStoragesGUI (see VARARGIN)

            % Choose default command line output for lvd_EditElectricalPowerStoragesGUI
            handles.output = hObject;
            
            centerUIFigure(app.lvd_EditElectricalPowerStoragesGUI);
            applySelectedThemeToApp(app);

            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            populateGUI(app, handles, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditElectricalPowerStoragesGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditElectricalPowerStoragesGUI);
        end

        % Button pushed function: addStorageButton
        function addStorageButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addStorageButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditElectricalPowerStoragesGUI,'lvdData');

            storagesListBoxStr = LaunchVehiclePowerStorageEnum.getListBoxStr();
            out = AppDesignerGUIOutput();
            listdlgARH_App('ListString', storagesListBoxStr, ...
                            'SelectionMode','single', ...
                            'Name', 'New Power Storage Type', ...
                            'PromptString','Select type of new power storage:', ...
                            'ListSize',[300,300], ...
                            'out',out);
            Selection = out.output{1};
            ok = out.output{2};

            if(ok == 1)
                initStage = lvdData.launchVehicle.getStageForInd(1);

                storageEnum = LaunchVehiclePowerStorageEnum.getEnumForListboxStr(storagesListBoxStr{Selection});
                switch storageEnum
                    case LaunchVehiclePowerStorageEnum.Basic
                        powerStorage = LaunchVehicleBasicElectricalBattery(initStage);

                    otherwise
                        error('Unknown Power Storage Type: %s', storageEnum.name)
                end

                useTF = powerStorage.openEditDialog();

                if(useTF)
                    powerStorage.getAttachedStage().addPwrStorage(powerStorage);
                    
                    stageStates = lvdData.initStateModel.stageStates;
                    stageStateInd = find([stageStates.stage] == powerStorage.stage,1,'first');
                    stageState = stageStates(stageStateInd);

                    newStorageState = powerStorage.createDefaultInitialState(stageState);
                    stageState.addPowerStorageState(newStorageState);

                    [~, ~] = app.setListboxStr();
                end
            end
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to closeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditElectricalPowerStoragesGUI);
        end

        % Menu selected function: copyStorageMenu
        function copyStorageMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to copyStorageMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditElectricalPowerStoragesGUI,'lvdData');
%             lv = lvdData.launchVehicle;

            powerStorage = app.storageListBox.Value;

            newPowerStorage = powerStorage.copy();
            initStage = newPowerStorage.getAttachedStage();

            initStage.addPwrStorage(newPowerStorage);

            stageStates = lvdData.initStateModel.stageStates;
            stageStateInd = find([stageStates.stage] == initStage,1,'first');
            stageState = stageStates(stageStateInd);

            newSinkState = newPowerStorage.createDefaultInitialState(stageState);
            stageState.addPowerStorageState(newSinkState);

            [~, ~] = app.setListboxStr();
        end

        % Window key press function: lvd_EditElectricalPowerStoragesGUI
        function lvd_EditElectricalPowerStoragesGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditElectricalPowerStoragesGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            switch(eventdata.Key)
                case 'return'
                    close(handles.lvd_EditElectricalPowerStoragesGUI);
                case 'enter'
                    close(handles.lvd_EditElectricalPowerStoragesGUI);
                case 'escape'
                    close(handles.lvd_EditElectricalPowerStoragesGUI);
            end
        end

        % Button pushed function: removeStorageButton
        function removeStorageButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeStorageButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditElectricalPowerStoragesGUI,'lvdData');
%             lv = lvdData.launchVehicle;

            powerStorage = app.storageListBox.Value;

            tf = powerStorage.isInUse();

            if(tf == false)
                stage = powerStorage.getAttachedStage();

                stageStates = lvdData.initStateModel.stageStates;
                stageStateInd = find([stageStates.stage] == stage,1,'first');
                stageState = stageStates(stageStateInd);

                stageState.removePowerStorageStateForPowerState(powerStorage);

                stage.removePwrStorage(powerStorage);

                [~, ~] = app.setListboxStr();
            else
                msg = sprintf('Could not delete the electical power storage "%s" because it is in use as part of an event termination condition, event action, objective function, or constraint.  Remove the dependencies before attempting to delete the electical power storage.', powerStorage.getName());
%                 warndlg(msg,'Cannot Delete Power Storage','modal');
                uialert(app.lvd_EditElectricalPowerStoragesGUI, msg, 'Cannot Delete Power Storage',"Modal",true);
            end
        end

        % Value changed function: storageListBox
        function storageListBox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to storageListBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns storageListBox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from storageListBox
            if(strcmpi(get(handles.lvd_EditElectricalPowerStoragesGUI,'SelectionType'),'open'))
                lvdData = getappdata(handles.lvd_EditElectricalPowerStoragesGUI,'lvdData');
%                 lv = lvdData.launchVehicle;

                powerStorage = app.storageListBox.Value;

                stageState = getStageStateForPowerState(app, powerStorage, lvdData);
                oldStorageState = stageState.getStateForPowerStorage(powerStorage);
                stageState.removePowerStorageStateForPowerState(powerStorage);

                powerStorage.openEditDialog();

                stageState = getStageStateForPowerState(app, powerStorage, lvdData);
                newPowerStorageState = powerStorage.createDefaultInitialState(stageState);

                if(not(isempty(oldStorageState)))
                    newPowerStorageState.setActiveState(oldStorageState.getActiveState());
                end
                stageState.addPowerStorageState(newPowerStorageState);

                [~, ~] = app.setListboxStr();
            end
        end

        % Window button down function: lvd_EditElectricalPowerStoragesGUI
        function lvd_EditElectricalPowerStoragesGUIWindowButtonDown(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            selType = app.lvd_EditElectricalPowerStoragesGUI.SelectionType;
            curObj = app.lvd_EditElectricalPowerStoragesGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.storageListBox)
                    lvdData = getappdata(handles.lvd_EditElectricalPowerStoragesGUI,'lvdData');
    
                    powerStorage = app.storageListBox.Value;
    
                    stageState = getStageStateForPowerState(app, powerStorage, lvdData);
                    oldStorageState = stageState.getStateForPowerStorage(powerStorage);
                    stageState.removePowerStorageStateForPowerState(powerStorage);
    
                    powerStorage.openEditDialog();
    
                    stageState = getStageStateForPowerState(app, powerStorage, lvdData);
                    newPowerStorageState = powerStorage.createDefaultInitialState(stageState);
    
                    if(not(isempty(oldStorageState)))
                        newPowerStorageState.setActiveState(oldStorageState.getActiveState());
                    end
                    stageState.addPowerStorageState(newPowerStorageState);
    
                    [~, ~] = app.setListboxStr();
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditElectricalPowerStoragesGUI = uifigure('Visible', 'off');
            app.lvd_EditElectricalPowerStoragesGUI.Name = 'Edit Electrical Power Storage';
            app.lvd_EditElectricalPowerStoragesGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditElectricalPowerStoragesGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_EditElectricalPowerStoragesGUIWindowButtonDown, true);
            app.lvd_EditElectricalPowerStoragesGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditElectricalPowerStoragesGUI_WindowKeyPressFcn, true);
            app.lvd_EditElectricalPowerStoragesGUI.Tag = 'lvd_EditElectricalPowerStoragesGUI';
            app.GridLayout = uigridlayout(app.lvd_EditElectricalPowerStoragesGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Electrical Power Storage';
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Icon = 'close_window(1).png';
            app.closeButton.Text = 'Close';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.storageListBox = uilistbox(app.GridLayout2);
            app.storageListBox.Items = {'Listbox'};
            app.storageListBox.ValueChangedFcn = createCallbackFcn(app, @storageListBox_Callback, true);
            app.storageListBox.Tag = 'storageListBox';
            app.storageListBox.Value = 'Listbox';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.addStorageButton = uibutton(app.GridLayout3, 'push');
            app.addStorageButton.ButtonPushedFcn = createCallbackFcn(app, @addStorageButton_Callback, true);
            app.addStorageButton.Tag = 'addStorageButton';
            app.addStorageButton.Icon = 'add.png';
            app.addStorageButton.Text = 'Add Power Storage';
            app.removeStorageButton = uibutton(app.GridLayout3, 'push');
            app.removeStorageButton.ButtonPushedFcn = createCallbackFcn(app, @removeStorageButton_Callback, true);
            app.removeStorageButton.Tag = 'removeStorageButton';
            app.removeStorageButton.Icon = 'remove.png';
            app.removeStorageButton.Text = 'Remove Power Storage';
            app.storageListboxContextMenu = uicontextmenu(app.lvd_EditElectricalPowerStoragesGUI);
            app.storageListboxContextMenu.Tag = 'storageListboxContextMenu';
            app.copyStorageMenu = uimenu(app.storageListboxContextMenu);
            app.copyStorageMenu.MenuSelectedFcn = createCallbackFcn(app, @copyStorageMenu_Callback, true);
            app.copyStorageMenu.Text = 'Create Copy of Selected Storage';
            app.copyStorageMenu.Tag = 'copyStorageMenu';
            % Assign app.storageListboxContextMenu
            app.storageListBox.ContextMenu = app.storageListboxContextMenu;
            % Show the figure after all components are created
            app.lvd_EditElectricalPowerStoragesGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditElectricalPowerStoragesGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditElectricalPowerStoragesGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditElectricalPowerStoragesGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditElectricalPowerStoragesGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditElectricalPowerStoragesGUI)
        end
    end
end