classdef lvd_EditCalculusObjGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditCalculusObjGUI  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        uipanel1                matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        uipanel2                matlab.ui.container.Panel
        GridLayout5             matlab.ui.container.GridLayout
        GridLayout7             matlab.ui.container.GridLayout
        setFrameOptionsButton   matlab.ui.control.Button
        GridLayout6             matlab.ui.container.GridLayout
        refFrameTypeCombo       matlab.ui.control.DropDown
        text18                  matlab.ui.control.Label
        refFrameNameLabel       matlab.ui.control.Label
        GridLayout4             matlab.ui.container.GridLayout
        calcObjQuantCombo       matlab.ui.control.DropDown
        extremaQuantLabel       matlab.ui.control.Label
        GridLayout2             matlab.ui.container.GridLayout
        cancelButton            matlab.ui.control.Button
        saveAndCloseButton      matlab.ui.control.Button
        text2                   matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function bodyInfo = getSelectedBodyInfo(app, handles)
            curFrame = getappdata(handles.lvd_EditCalculusObjGUI,'frame');
            bodyInfo = curFrame.getOriginBody();
        end

        function populateGUI(app, handles, calcObj, lvdData)
            exclude = getLvdGAExcludeList();
            exclude{end+1} = 'Distance Traveled';
            [extremaGAStr, ~] = lvdData.launchVehicle.getExtremaGraphAnalysisTaskStrs();
            exclude = horzcat(exclude,extremaGAStr);
            taskList = lvd_getGraphAnalysisTaskList(lvdData, exclude);

            handles.calcObjQuantCombo.String = taskList;
            if(isempty(calcObj.quantStr))
                value = 1;
            else
                value = findValueFromComboBox(calcObj.quantStr, handles.calcObjQuantCombo);
            end
            handles.calcObjQuantCombo.Value = value;

            %     populateBodiesCombo(lvdData.celBodyData, handles.refFrameTypeCombo);
            %     if(isempty(calcObj.refBody))
            %         value = 1;
            %     else
            %         value = findValueFromComboBox(calcObj.refBody.name, handles.refFrameTypeCombo);
            %     end
            %     handles.refFrameTypeCombo.Value = value;

            frame = calcObj.frame;
            if(isempty(frame))
                frame = LvdData.getDefaultInitialBodyInfo(lvdData.celBodyData).getBodyCenteredInertialFrame();
                calcObj.frame = frame;
            end
            setappdata(handles.lvd_EditCalculusObjGUI,'frame',frame);

            handles.refFrameTypeCombo.String = ReferenceFrameEnum.getListBoxStr();
            [ind, ~] = ReferenceFrameEnum.getIndForName(frame.typeEnum.name);
            handles.refFrameTypeCombo.Value = ind;
            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', frame.getNameStr());
            handles.refFrameNameLabel.String = sprintf('%s', frame.getNameStr());
            handles.refFrameNameLabel.TooltipString = sprintf('%s', frame.getNameStr());
        end

        function updateFrameChange(app, handles)
            lvdData = getappdata(handles.lvd_EditCalculusObjGUI,'lvdData');
            celBodyData = lvdData.celBodyData;

            refEnumListBoxStr = ReferenceFrameEnum.getListBoxStr();
            selFrameTypeInd = handles.refFrameTypeCombo.Value;
            refFrameEnum = ReferenceFrameEnum.getEnumForListboxStr(refEnumListBoxStr{selFrameTypeInd});

            %             if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
            %                 refFrameEnum = newFrame.typeEnum;
            %                 app.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
            %             end

            switch refFrameEnum
                case ReferenceFrameEnum.BodyCenteredInertial
                    bodyInfo = getSelectedBodyInfo(app, handles);

                    newFrame = bodyInfo.getBodyCenteredInertialFrame();

                case ReferenceFrameEnum.BodyFixedRotating
                    bodyInfo = getSelectedBodyInfo(app, handles);

                    newFrame = bodyInfo.getBodyFixedFrame();

                case ReferenceFrameEnum.TwoBodyRotating
                    bodyInfo = getSelectedBodyInfo(app, handles);
                    if(not(isempty(bodyInfo.childrenBodyInfo)))
                        primaryBody = bodyInfo;
                        secondaryBody = bodyInfo.childrenBodyInfo(1);
                    else
                        primaryBody = bodyInfo.getParBodyInfo();
                        secondaryBody = bodyInfo;
                    end

                    originPt = TwoBodyRotatingFrameOriginEnum.Primary;

                    newFrame = TwoBodyRotatingFrame(primaryBody, secondaryBody, originPt, celBodyData);

                case ReferenceFrameEnum.UserDefined
                    numFrames = lvdData.geometry.refFrames.getNumRefFrames();
                    if(numFrames >= 1)
                        geometricFrame = AbstractGeometricRefFrame.empty(1,0);
                        for(i=1:numFrames)
                            frame = lvdData.geometry.refFrames.getRefFrameAtInd(i);
                            if(frame.isVehDependent() == false)
                                geometricFrame = frame;
                                break;
                            end
                        end

                        if(not(isempty(geometricFrame)))
                            newFrame = UserDefinedGeometricFrame(geometricFrame, lvdData);
                        else
                            bodyInfo = getSelectedBodyInfo(app, handles);
                            newFrame = bodyInfo.getBodyCenteredInertialFrame();

                            warndlg('There are no geometric frames available which are not dependent on vehicle properties.  A body-centered inertial frame will be selected instead.');
                        end
                    else
                        bodyInfo = getSelectedBodyInfo(app, handles);
                        newFrame = bodyInfo.getBodyCenteredInertialFrame();

                        warndlg('There are no geometric frames available which are not dependent on vehicle properties.  A body-centered inertial frame will be selected instead.');
                    end

                otherwise
                    error('Unknown reference frame type: %s', string(refFrameEnum));
            end

            if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
                refFrameEnum = newFrame.typeEnum;
                handles.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
            end

            setappdata(handles.lvd_EditCalculusObjGUI,'frame', newFrame);
            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', newFrame.getNameStr());
            handles.refFrameNameLabel.String = sprintf('%s', newFrame.getNameStr());
            handles.refFrameNameLabel.TooltipString = sprintf('%s', newFrame.getNameStr());
        end


        function saveAndClose(app, handles)
            calcObj = getappdata(handles.lvd_EditCalculusObjGUI, 'calcObj');
            %         lvdData = getappdata(handles.lvd_EditCalculusObjGUI, 'lvdData');

            quantityStrs = handles.calcObjQuantCombo.String;
            quantity = strtrim(quantityStrs{handles.calcObjQuantCombo.Value});
            calcObj.quantStr = quantity;

            frame = getappdata(handles.lvd_EditCalculusObjGUI,'frame');
            calcObj.frame = frame;

            app.output.output = {true};
            close(handles.lvd_EditCalculusObjGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditCalculusObjGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditCalculusObjGUI (see VARARGIN)

            % Choose default command line output for lvd_EditCalculusObjGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            calcObj = varargin{1};
            setappdata(hObject, 'calcObj', calcObj);

            lvdData = varargin{2};
            setappdata(hObject, 'lvdData', lvdData);
            
            app.output = varargin{3};

            populateGUI(app, handles, calcObj, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditCalculusObjGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditCalculusObjGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditCalculusObjGUI);
        end

        % Window key press function: lvd_EditCalculusObjGUI
        function lvd_EditCalculusObjGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditCalculusObjGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditCalculusObjGUI);
            end
        end

        % Value changed function: refFrameTypeCombo
        function refFrameTypeCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to refFrameTypeCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns refFrameTypeCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from refFrameTypeCombo
            updateFrameChange(app, handles);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.saveAndClose(handles);
        end

        % Button pushed function: setFrameOptionsButton
        function setFrameOptionsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to setFrameOptionsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            curFrame = getappdata(handles.lvd_EditCalculusObjGUI,'frame');
            curFrame = curFrame.editFrameDialogUI(EditReferenceFrameContextEnum.ForState);

            setappdata(handles.lvd_EditCalculusObjGUI,'frame',curFrame);

            handles.setFrameOptionsButton.Tooltip = sprintf('Current Frame: %s', curFrame.getNameStr());
            handles.refFrameNameLabel.String = sprintf('%s', curFrame.getNameStr());
            handles.refFrameNameLabel.TooltipString = sprintf('%s', curFrame.getNameStr());
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditCalculusObjGUI = uifigure('Visible', 'off');
            app.lvd_EditCalculusObjGUI.Name = 'Edit Calculus Calculation';
            app.lvd_EditCalculusObjGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditCalculusObjGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditCalculusObjGUI_WindowKeyPressFcn, true);
            app.lvd_EditCalculusObjGUI.Tag = 'lvd_EditCalculusObjGUI';
            app.GridLayout = uigridlayout(app.lvd_EditCalculusObjGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Calculus Calculation';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.extremaQuantLabel = uilabel(app.GridLayout4);
            app.extremaQuantLabel.Tag = 'extremaQuantLabel';
            app.extremaQuantLabel.Text = 'Extrema Quantity';
            app.calcObjQuantCombo = uidropdown(app.GridLayout4);
            app.calcObjQuantCombo.Items = {'ENGINE NAME'};
            app.calcObjQuantCombo.Tag = 'calcObjQuantCombo';
            app.calcObjQuantCombo.Value = 'ENGINE NAME';
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Reference Frame';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout5 = uigridlayout(app.uipanel2);
            app.refFrameNameLabel = uilabel(app.GridLayout5);
            app.refFrameNameLabel.Tag = 'refFrameNameLabel';
            app.refFrameNameLabel.Text = 'Static Text';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.text18 = uilabel(app.GridLayout6);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Frame Type';
            app.refFrameTypeCombo = uidropdown(app.GridLayout6);
            app.refFrameTypeCombo.Items = {'False', 'True'};
            app.refFrameTypeCombo.ValueChangedFcn = createCallbackFcn(app, @refFrameTypeCombo_Callback, true);
            app.refFrameTypeCombo.Tag = 'refFrameTypeCombo';
            app.refFrameTypeCombo.Value = 'False';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.setFrameOptionsButton = uibutton(app.GridLayout7, 'push');
            app.setFrameOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @setFrameOptionsButton_Callback, true);
            app.setFrameOptionsButton.Tag = 'setFrameOptionsButton';
            app.setFrameOptionsButton.Icon = 'reference_frame.png';
            app.setFrameOptionsButton.Tooltip = 'Sets parameters for the selected reference frame type.';
            app.setFrameOptionsButton.Text = 'Set Frame Options';
            % Show the figure after all components are created
            app.lvd_EditCalculusObjGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditCalculusObjGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditCalculusObjGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditCalculusObjGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditCalculusObjGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditCalculusObjGUI)
        end
    end
end