classdef lvd_EditCalculusObjsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditCalculusObjsGUI  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        Panel                    matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        GridLayout3              matlab.ui.container.GridLayout
        removeCalcObjButton      matlab.ui.control.Button
        addCalcObjButton         matlab.ui.control.Button
        calcObjListBox           matlab.ui.control.ListBox
        closeButton              matlab.ui.control.Button
        text2                    matlab.ui.control.Label
    end


    methods (Access = private)
        function populateGUI(app, ~, ~)
            [~, ~] = app.setListboxStr();
        end

        function [calcListStr, calcObjs] = setListboxStr(app)
            lvdData = getappdata(app.lvd_EditCalculusObjsGUI,'lvdData');
            [calcListStr, calcObjs] = lvdData.launchVehicle.getCalculusCalcObjListBoxStr();
            app.calcObjListBox.Items = calcListStr;
            app.calcObjListBox.ItemsData = calcObjs;
            
            num = numel(calcObjs);
            if(num <= 0)
                app.removeCalcObjButton.Enable = 'off';
            else
                app.removeCalcObjButton.Enable = 'on';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditCalculusObjsGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditCalculusObjsGUI (see VARARGIN)

            % Choose default command line output for lvd_EditCalculusObjsGUI
            handles.output = hObject;
            
            centerUIFigure(app.lvd_EditCalculusObjsGUI);
            applySelectedThemeToApp(app);

            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            populateGUI(app, handles, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditCalculusObjsGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditCalculusObjsGUI);
        end

        % Button pushed function: addCalcObjButton
        function addCalcObjButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addCalcObjButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditCalculusObjsGUI,'lvdData');

            listBoxStr = CalculusCalculationEnum.getListBoxStr();
            out = AppDesignerGUIOutput();
            listdlgARH_App('ListString',listBoxStr, ...
                'SelectionMode','single', ...
                'PromptString','Select the type of calculus calculation you wish to perform:', ...
                'Name','Calculation Type', ...
                'ListSize', [300 300],...
                'out',out);
            Selection = out.output{1};
            ok = out.output{2};

            if(ok == 1)
                calcObjEnum = CalculusCalculationEnum.getEnumForListboxStr(listBoxStr{Selection});
                switch calcObjEnum
                    case CalculusCalculationEnum.Derivative
                        calcObj = LaunchVehicleDerivativeCalc(lvdData);

                    case CalculusCalculationEnum.Integral
                        calcObj = LaunchVehicleIntegralCalc(lvdData);

                    otherwise
                        error('Invalid calculus calculation type: %s', class(calcObjEnum));
                end

%                 useCalcObj = lvd_EditCalculusObjGUI(calcObj, lvdData);

                output = AppDesignerGUIOutput({false});
                lvd_EditCalculusObjGUI_App(calcObj, lvdData, output);
                useCalcObj = output.output{1};

                if(useCalcObj)
                    lvdData.launchVehicle.addCalculusCalcObj(calcObj);

                    [~, ~] = app.setListboxStr();
                end
            end
        end

        % Value changed function: calcObjListBox
        function calcObjListBox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to calcObjListBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns calcObjListBox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from calcObjListBox
%             if(strcmpi(get(handles.lvd_EditCalculusObjsGUI,'SelectionType'),'open'))
%                 lvdData = getappdata(handles.lvd_EditCalculusObjsGUI,'lvdData');
%                 lv = lvdData.launchVehicle;
% 
%                 selCalcObj = get(handles.calcObjListBox,'Value');
%                 calcObj = lv.getCalculusCalcObjForInd(selCalcObj);
% 
%                 lvd_EditCalculusObjGUI(calcObj, lvdData);
% 
%                 [~, ~] = app.setListboxStr();
%             end
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to closeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditCalculusObjsGUI);
        end

        % Window key press function: lvd_EditCalculusObjsGUI
        function lvd_EditCalculusObjsGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditCalculusObjsGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            switch(eventdata.Key)
                case 'return'
                    close(handles.lvd_EditCalculusObjsGUI);
                case 'enter'
                    close(handles.lvd_EditCalculusObjsGUI);
                case 'escape'
                    close(handles.lvd_EditCalculusObjsGUI);
            end
        end

        % Button pushed function: removeCalcObjButton
        function removeCalcObjButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeCalcObjButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditCalculusObjsGUI,'lvdData');
            lv = lvdData.launchVehicle;

            calcObj = app.calcObjListBox.Value;

            tf = calcObj.isInUse();

            if(tf == false)
                lv.removeCalculusCalcObj(calcObj);

                [~, ~] = app.setListboxStr();
            else
                msg = sprintf('Could not delete the calculus calculation "%s" because it is in use as part of an event termination condition, event action, objective function, or constraint.  Remove these dependencies before attempting to delete the calculus calculation.', calcObj.getNameStr());
%                 warndlg(msg,'Cannot Delete Calculus Calculation','modal');
                uialert(app.lvd_EditCalculusObjsGUI, msg, 'Cannot Delete Calculus Calculation',"Modal",true);
            end
        end

        % Window button down function: lvd_EditCalculusObjsGUI
        function lvd_EditCalculusObjsGUIWindowButtonDown(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            selType = app.lvd_EditCalculusObjsGUI.SelectionType;
            curObj = app.lvd_EditCalculusObjsGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.calcObjListBox && numel(app.calcObjListBox.ItemsData) > 0)
                    lvdData = getappdata(handles.lvd_EditCalculusObjsGUI,'lvdData');
%                     lv = lvdData.launchVehicle;
    
                    calcObj = app.calcObjListBox.Value;
    
%                     lvd_EditCalculusObjGUI(calcObj, lvdData);
                    output = AppDesignerGUIOutput({false});
                    lvd_EditCalculusObjGUI_App(calcObj, lvdData, output);
    
                    [~, ~] = app.setListboxStr();
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditCalculusObjsGUI = uifigure('Visible', 'off');
            app.lvd_EditCalculusObjsGUI.Name = 'Edit Calculus Calculations';
            app.lvd_EditCalculusObjsGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditCalculusObjsGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_EditCalculusObjsGUIWindowButtonDown, true);
            app.lvd_EditCalculusObjsGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditCalculusObjsGUI_WindowKeyPressFcn, true);
            app.lvd_EditCalculusObjsGUI.Tag = 'lvd_EditCalculusObjsGUI';
            app.GridLayout = uigridlayout(app.lvd_EditCalculusObjsGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Calculus Calculations';
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Icon = 'close_window(1).png';
            app.closeButton.Text = 'Close';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.calcObjListBox = uilistbox(app.GridLayout2);
            app.calcObjListBox.Items = {'Listbox'};
            app.calcObjListBox.ValueChangedFcn = createCallbackFcn(app, @calcObjListBox_Callback, true);
            app.calcObjListBox.Tag = 'calcObjListBox';
            app.calcObjListBox.Tooltip = 'Calculus calculations allow you to compute derivatives and definite integrals of quantities.';
            app.calcObjListBox.Value = 'Listbox';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.addCalcObjButton = uibutton(app.GridLayout3, 'push');
            app.addCalcObjButton.ButtonPushedFcn = createCallbackFcn(app, @addCalcObjButton_Callback, true);
            app.addCalcObjButton.Tag = 'addCalcObjButton';
            app.addCalcObjButton.Icon = 'add.png';
            app.addCalcObjButton.Text = 'Add Calculation';
            app.removeCalcObjButton = uibutton(app.GridLayout3, 'push');
            app.removeCalcObjButton.ButtonPushedFcn = createCallbackFcn(app, @removeCalcObjButton_Callback, true);
            app.removeCalcObjButton.Tag = 'removeCalcObjButton';
            app.removeCalcObjButton.Icon = 'remove.png';
            app.removeCalcObjButton.Text = 'Remove Calculation';
            % Show the figure after all components are created
            app.lvd_EditCalculusObjsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditCalculusObjsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditCalculusObjsGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditCalculusObjsGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditCalculusObjsGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditCalculusObjsGUI)
        end
    end
end