classdef lvd_EditEngineGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditEngineGUI             matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        uipanel1                      matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        GridLayout7                   matlab.ui.container.GridLayout
        text16                        matlab.ui.control.Label
        maxThrottleText               matlab.ui.control.EditField
        text17                        matlab.ui.control.Label
        GridLayout6                   matlab.ui.container.GridLayout
        minThrottleText               matlab.ui.control.EditField
        text14                        matlab.ui.control.Label
        text15                        matlab.ui.control.Label
        GridLayout5                   matlab.ui.container.GridLayout
        stageCombo                    matlab.ui.control.DropDown
        text13                        matlab.ui.control.Label
        GridLayout4                   matlab.ui.container.GridLayout
        engineNameText                matlab.ui.control.EditField
        stageName                     matlab.ui.control.Label
        uipanel3                      matlab.ui.container.Panel
        GridLayout9                   matlab.ui.container.GridLayout
        GridLayout11                  matlab.ui.container.GridLayout
        text21                        matlab.ui.control.Label
        elecEnginePwrConsumptionText  matlab.ui.control.EditField
        text20                        matlab.ui.control.Label
        reqsElecChargeCheckbox        matlab.ui.control.CheckBox
        uipanel2                      matlab.ui.container.Panel
        GridLayout8                   matlab.ui.container.GridLayout
        GridLayout10                  matlab.ui.container.GridLayout
        text19                        matlab.ui.control.Label
        altChargeRateText             matlab.ui.control.EditField
        text18                        matlab.ui.control.Label
        hasAlternatorCheckbox         matlab.ui.control.CheckBox
        editThrottleModifierProfileButton  matlab.ui.control.Button
        editIspProfileButton          matlab.ui.control.Button
        editThrustProfileButton       matlab.ui.control.Button
        GridLayout2                   matlab.ui.container.GridLayout
        cancelButton                  matlab.ui.control.Button
        saveAndCloseButton            matlab.ui.control.Button
        text2                         matlab.ui.control.Label
        throttleThrustIspContextMenu  matlab.ui.container.ContextMenu
        importEngineParamsFromKsp     matlab.ui.container.Menu
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, engine)
            lv = engine.stage.launchVehicle;
            [stagesListboxStr, ~] = lv.getStagesListBoxStr();
            ind = lv.getIndOfStage(engine.stage);

            if(isempty(ind))
                ind = 1;
            end

            set(handles.engineNameText,'String',engine.name);
            set(handles.stageCombo,'String',stagesListboxStr);
            set(handles.stageCombo,'Value',ind);
            %     set(handles.vacThrustText,'String',fullAccNum2Str(engine.getVacThrust()));
            %     set(handles.vacIspText,'String',fullAccNum2Str(engine.getVacIsp()));
            %     set(handles.seaLevelThrustText,'String',fullAccNum2Str(engine.getSeaLvlThrust()));
            %     set(handles.seaLevelIspText,'String',fullAccNum2Str(engine.getSeaLvlIsp()));
            set(handles.minThrottleText,'String',fullAccNum2Str(100*engine.getMinThrottle()));
            set(handles.maxThrottleText,'String',fullAccNum2Str(100*engine.getMaxThrottle()));

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            handles.hasAlternatorCheckbox.Value = double(engine.hasAlternator);
            handles.altChargeRateText.String = fullAccNum2Str(engine.altPwrRate);
            hasAlternatorCheckbox_Callback(app, evt);

            handles.reqsElecChargeCheckbox.Value = double(engine.reqsElecCharge);
            handles.elecEnginePwrConsumptionText.String = fullAccNum2Str(-1*engine.pwrUsageRate);
            reqsElecChargeCheckbox_Callback(app, evt);
        end

        function seaLevelIspText_Callback(app, hObject, eventdata, handles)
            % hObject    handle to seaLevelIspText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of seaLevelIspText as text
            %        str2double(get(hObject,'String')) returns contents of seaLevelIspText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        function seaLevelIspText_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to seaLevelIspText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function seaLevelThrustText_Callback(app, hObject, eventdata, handles)
            % hObject    handle to seaLevelThrustText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of seaLevelThrustText as text
            %        str2double(get(hObject,'String')) returns contents of seaLevelThrustText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        function seaLevelThrustText_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to seaLevelThrustText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function vacIspText_Callback(app, hObject, eventdata, handles)
            % hObject    handle to vacIspText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of vacIspText as text
            %        str2double(get(hObject,'String')) returns contents of vacIspText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        function vacIspText_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to vacIspText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function vacThrustText_Callback(app, hObject, eventdata, handles)
            % hObject    handle to vacThrustText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of vacThrustText as text
            %        str2double(get(hObject,'String')) returns contents of vacThrustText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        function vacThrustText_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to vacThrustText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            %     val = str2double(get(handles.vacThrustText,'String'));
            %     enteredStr = get(handles.vacThrustText,'String');
            %     numberName = 'Vacuum Thrust';
            %     lb = 0;
            %     ub = Inf;
            %     isInt = false;
            %     errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
            %
            %     val = str2double(get(handles.vacIspText,'String'));
            %     enteredStr = get(handles.vacIspText,'String');
            %     numberName = 'Vacuum Isp';
            %     lb = 0;
            %     ub = Inf;
            %     isInt = false;
            %     errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
            %
            %     val = str2double(get(handles.seaLevelThrustText,'String'));
            %     enteredStr = get(handles.seaLevelThrustText,'String');
            %     numberName = 'Sea Level Thrust';
            %     lb = 0;
            %     ub = Inf;
            %     isInt = false;
            %     errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
            %
            %     val = str2double(get(handles.seaLevelIspText,'String'));
            %     enteredStr = get(handles.seaLevelIspText,'String');
            %     numberName = 'Sea Level Isp';
            %     lb = 0;
            %     ub = Inf;
            %     isInt = false;
            %     errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);

            minThrottle = str2double(get(handles.minThrottleText,'String'));
            enteredStr = get(handles.minThrottleText,'String');
            numberName = 'Minimum Throttle Setting';
            lb = 0;
            ub = 100;
            isInt = false;
            errMsg = validateNumber(minThrottle, numberName, lb, ub, isInt, errMsg, enteredStr);

            maxThrottle = str2double(get(handles.maxThrottleText,'String'));
            enteredStr = get(handles.maxThrottleText,'String');
            numberName = 'Maximum Throttle Setting';
            lb = 0;
            ub = 100;
            isInt = false;
            errMsg = validateNumber(maxThrottle, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                maxThrottle = str2double(get(handles.maxThrottleText,'String'));
                enteredStr = get(handles.maxThrottleText,'String');
                numberName = 'Maximum Throttle Setting';
                lb = minThrottle;
                ub = 100;
                isInt = false;
                errMsg = validateNumber(maxThrottle, numberName, lb, ub, isInt, errMsg, enteredStr);
            end

            altChargeRate = str2double(get(handles.altChargeRateText,'String'));
            enteredStr = get(handles.altChargeRateText,'String');
            numberName = 'Alternator Charge Rate';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(altChargeRate, numberName, lb, ub, isInt, errMsg, enteredStr);

            elecEngDischargeRate = str2double(get(handles.elecEnginePwrConsumptionText,'String'));
            enteredStr = get(handles.elecEnginePwrConsumptionText,'String');
            numberName = 'Electric Engine Discharge Rate';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(elecEngDischargeRate, numberName, lb, ub, isInt, errMsg, enteredStr);
        end

        function saveAndClose(app, handles)
            engine = getappdata(handles.lvd_EditEngineGUI, 'engine');
            lv = engine.stage.launchVehicle;

            conns = lv.getEngineToTankConnsForEngine(engine);

            stage = lv.getStageForInd(handles.stageCombo.Value);

            name = handles.engineNameText.String;
            minThrottle = str2double(handles.minThrottleText.String)/100;
            maxThrottle = str2double(handles.maxThrottleText.String)/100;

            hasAlternator = logical(handles.hasAlternatorCheckbox.Value);
            altChargeRate = str2double(handles.altChargeRateText.String);

            reqsElecCharge = logical(handles.reqsElecChargeCheckbox.Value);
            elecEnginePwrConsumption = -1*str2double(handles.elecEnginePwrConsumptionText.String);

            engine.name = name;
            engine.stage = stage;
            engine.minThrottle = minThrottle;
            engine.maxThrottle = maxThrottle;
            engine.hasAlternator = hasAlternator;
            engine.altPwrRate = altChargeRate;
            engine.reqsElecCharge = reqsElecCharge;
            engine.pwrUsageRate = elecEnginePwrConsumption;

            lv.moveEngineToStage(engine, stage);

            for(i=1:length(conns)) %#ok<NO4LP>
                conns(i).engine = engine;
                lv.addEngineToTankConnection(conns(i));
            end

            app.output.output = {true};
            close(handles.lvd_EditEngineGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditEngineGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditEngineGUI (see VARARGIN)

            % Choose default command line output for lvd_EditEngineGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app)

            engine = varargin{1};
            setappdata(hObject, 'engine', engine);
            
            app.output = varargin{2};

            populateGUI(app, handles, engine);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditEngineGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditEngineGUI);
        end

        % Value changed function: altChargeRateText
        function altChargeRateText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to altChargeRateText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of altChargeRateText as text
            %        str2double(get(hObject,'String')) returns contents of altChargeRateText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditEngineGUI);
        end

        % Button pushed function: editIspProfileButton
        function editIspProfileButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editIspProfileButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            engine = getappdata(handles.lvd_EditEngineGUI, 'engine');
            
            output2 = AppDesignerGUIOutput({false});
            editThrottleModifierProfileGUI_App(engine.ispPressCurve, output2);
        end

        % Button pushed function: editThrottleModifierProfileButton
        function editThrottleModifierProfileButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editThrottleModifierProfileButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            engine = getappdata(handles.lvd_EditEngineGUI, 'engine');
            
            output2 = AppDesignerGUIOutput({false});
            editThrottleModifierProfileGUI_App(engine.fuelThrottleCurve, output2);
        end

        % Button pushed function: editThrustProfileButton
        function editThrustProfileButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editThrustProfileButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            engine = getappdata(handles.lvd_EditEngineGUI, 'engine');
            
            output2 = AppDesignerGUIOutput({false});
            editThrottleModifierProfileGUI_App(engine.thrustPressCurve, output2);
        end

        % Value changed function: elecEnginePwrConsumptionText
        function elecEnginePwrConsumptionText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to elecEnginePwrConsumptionText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of elecEnginePwrConsumptionText as text
            %        str2double(get(hObject,'String')) returns contents of elecEnginePwrConsumptionText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: hasAlternatorCheckbox
        function hasAlternatorCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.hasAlternatorCheckbox;

            % hObject    handle to hasAlternatorCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of hasAlternatorCheckbox
            if(get(hObject,'Value'))
                handles.altChargeRateText.Enable = 'on';
            else
                handles.altChargeRateText.Enable = 'off';
            end
        end

        % Menu selected function: importEngineParamsFromKsp
        function importEngineParamsFromKsp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to importEngineParamsFromKsp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [FileName,PathName,~] = uigetfile({'*.cfg','KSP Engine Config Files'},'Import KSP Engine Data');

            if(FileName == 0)
                return;
            end

            engineCfgFile = fullfile(PathName,FileName);
            try
                [thrustCurve, ispCurve, maxThrottle, minThrottle, ~] = getEngineParametersFromKspEngineFile(engineCfgFile);
            catch ME
                errordlg(sprintf('Could not parse engine config file.  Message:\n\n%s', ME.message),'Could Not Parse Engine Config');
                return;
            end

            engine = getappdata(handles.lvd_EditEngineGUI, 'engine');

            engine.minThrottle = minThrottle;
            engine.maxThrottle = maxThrottle;
            engine.ispPressCurve = ispCurve;
            engine.thrustPressCurve = thrustCurve;

            populateGUI(app, handles, engine);

%             helpdlg('Engine data import was successful.','Engine Data Import');
            uialert(app.lvd_EditEngineGUI, 'Engine data import was successful.', 'Engine Data Import', 'Icon','success');
        end

        % Window key press function: lvd_EditEngineGUI
        function lvd_EditEngineGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditEngineGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditEngineGUI);
            end
        end

        % Value changed function: maxThrottleText
        function maxThrottleText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to maxThrottleText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of maxThrottleText as text
            %        str2double(get(hObject,'String')) returns contents of maxThrottleText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: minThrottleText
        function minThrottleText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to minThrottleText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of minThrottleText as text
            %        str2double(get(hObject,'String')) returns contents of minThrottleText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: reqsElecChargeCheckbox
        function reqsElecChargeCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.reqsElecChargeCheckbox;

            % hObject    handle to reqsElecChargeCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of reqsElecChargeCheckbox
            if(get(hObject,'Value'))
                handles.elecEnginePwrConsumptionText.Enable = 'on';
            else
                handles.elecEnginePwrConsumptionText.Enable = 'off';
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
%                 uiresume(handles.lvd_EditEngineGUI);
                app.saveAndClose(handles);
            else
%                 msgbox(errMsg,'Invalid Engine Inputs','error');
                uialert(app.lvd_EditEngineGUI, errMsg, 'Invalid Engine Inputs', "Icon","error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditEngineGUI = uifigure('Visible', 'off');
            app.lvd_EditEngineGUI.Name = 'Edit Engine';
            app.lvd_EditEngineGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditEngineGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditEngineGUI_WindowKeyPressFcn, true);
            app.lvd_EditEngineGUI.Tag = 'lvd_EditEngineGUI';
            app.GridLayout = uigridlayout(app.lvd_EditEngineGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Engine';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.editThrustProfileButton = uibutton(app.GridLayout3, 'push');
            app.editThrustProfileButton.ButtonPushedFcn = createCallbackFcn(app, @editThrustProfileButton_Callback, true);
            app.editThrustProfileButton.Tag = 'editThrustProfileButton';
            app.editThrustProfileButton.Icon = 'rocket_engine_on.png';
            app.editThrustProfileButton.Text = 'Edit Thrust Profile';
            app.editIspProfileButton = uibutton(app.GridLayout3, 'push');
            app.editIspProfileButton.ButtonPushedFcn = createCallbackFcn(app, @editIspProfileButton_Callback, true);
            app.editIspProfileButton.Tag = 'editIspProfileButton';
            app.editIspProfileButton.Icon = 'gas-pump.png';
            app.editIspProfileButton.Text = 'Edit Specific Impulse Profile';
            app.editThrottleModifierProfileButton = uibutton(app.GridLayout3, 'push');
            app.editThrottleModifierProfileButton.ButtonPushedFcn = createCallbackFcn(app, @editThrottleModifierProfileButton_Callback, true);
            app.editThrottleModifierProfileButton.Tag = 'editThrottleModifierProfileButton';
            app.editThrottleModifierProfileButton.Icon = 'throttle.png';
            app.editThrottleModifierProfileButton.Text = 'Edit Throttle Modifier Profile';
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Alternator';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout8 = uigridlayout(app.uipanel2);
            app.hasAlternatorCheckbox = uicheckbox(app.GridLayout8);
            app.hasAlternatorCheckbox.ValueChangedFcn = createCallbackFcn(app, @hasAlternatorCheckbox_Callback, true);
            app.hasAlternatorCheckbox.Tag = 'hasAlternatorCheckbox';
            app.hasAlternatorCheckbox.Tooltip = 'If checked, this engine will produce electric charge when it is operating at throttle levels greater than zero percent (0%).';
            app.hasAlternatorCheckbox.Text = 'Has Alternator';
            app.GridLayout10 = uigridlayout(app.GridLayout8);
            app.text18 = uilabel(app.GridLayout10);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Charging Rate';
            app.altChargeRateText = uieditfield(app.GridLayout10, 'text');
            app.altChargeRateText.ValueChangedFcn = createCallbackFcn(app, @altChargeRateText_Callback, true);
            app.altChargeRateText.Tag = 'altChargeRateText';
            app.altChargeRateText.Tooltip = 'The rate at which this engine produces Electric Charge at full throttle (100%).';
            app.altChargeRateText.Value = '1';
            app.text19 = uilabel(app.GridLayout10);
            app.text19.Tag = 'text19';
            app.text19.Text = 'EC/s';
            app.uipanel3 = uipanel(app.GridLayout3);
            app.uipanel3.Title = 'Electric Engine';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout9 = uigridlayout(app.uipanel3);
            app.reqsElecChargeCheckbox = uicheckbox(app.GridLayout9);
            app.reqsElecChargeCheckbox.ValueChangedFcn = createCallbackFcn(app, @reqsElecChargeCheckbox_Callback, true);
            app.reqsElecChargeCheckbox.Tag = 'reqsElecChargeCheckbox';
            app.reqsElecChargeCheckbox.Tooltip = 'If checked, this engine will require electric charge to be provided from onboard power storage devices (ex: batteries) to function.  If there are no power storage devices onboard, this engine will not function.';
            app.reqsElecChargeCheckbox.Text = 'Requires Electric Charge (EC)';
            app.GridLayout11 = uigridlayout(app.GridLayout9);
            app.text20 = uilabel(app.GridLayout11);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Discharge Rate';
            app.elecEnginePwrConsumptionText = uieditfield(app.GridLayout11, 'text');
            app.elecEnginePwrConsumptionText.ValueChangedFcn = createCallbackFcn(app, @elecEnginePwrConsumptionText_Callback, true);
            app.elecEnginePwrConsumptionText.Tag = 'elecEnginePwrConsumptionText';
            app.elecEnginePwrConsumptionText.Tooltip = 'The rate at which this electric engine uses Electric Charge (EC) at full throttle (100%).';
            app.elecEnginePwrConsumptionText.Value = '1';
            app.text21 = uilabel(app.GridLayout11);
            app.text21.Tag = 'text21';
            app.text21.Text = 'EC/s';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.stageName = uilabel(app.GridLayout4);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Engine Name';
            app.engineNameText = uieditfield(app.GridLayout4, 'text');
            app.engineNameText.Tag = 'engineNameText';
            app.engineNameText.Value = 'ENGINE NAME';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.text13 = uilabel(app.GridLayout5);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Assigned Stage';
            app.stageCombo = uidropdown(app.GridLayout5);
            app.stageCombo.Items = {'Pop-up Menu'};
            app.stageCombo.Tag = 'stageCombo';
            app.stageCombo.Value = 'Pop-up Menu';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.text15 = uilabel(app.GridLayout6);
            app.text15.Tag = 'text15';
            app.text15.Text = '%';
            app.text14 = uilabel(app.GridLayout6);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Min Throttle';
            app.minThrottleText = uieditfield(app.GridLayout6, 'text');
            app.minThrottleText.ValueChangedFcn = createCallbackFcn(app, @minThrottleText_Callback, true);
            app.minThrottleText.Tag = 'minThrottleText';
            app.minThrottleText.Tooltip = 'The minimum throttle setting the engine will use.  If the throttle profile goes below this amount, then the engine will throttle to this setting.';
            app.minThrottleText.Value = '1';
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.text17 = uilabel(app.GridLayout7);
            app.text17.Tag = 'text17';
            app.text17.Text = '%';
            app.maxThrottleText = uieditfield(app.GridLayout7, 'text');
            app.maxThrottleText.ValueChangedFcn = createCallbackFcn(app, @maxThrottleText_Callback, true);
            app.maxThrottleText.Tag = 'maxThrottleText';
            app.maxThrottleText.Tooltip = 'The maximum throttle setting the engine will use.  If the throttle profile goes above this amount, then the engine will throttle to this setting.';
            app.maxThrottleText.Value = '1';
            app.text16 = uilabel(app.GridLayout7);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Max Throttle';
            app.throttleThrustIspContextMenu = uicontextmenu(app.lvd_EditEngineGUI);
            app.throttleThrustIspContextMenu.Tag = 'throttleThrustIspContextMenu';
            app.importEngineParamsFromKsp = uimenu(app.throttleThrustIspContextMenu);
            app.importEngineParamsFromKsp.MenuSelectedFcn = createCallbackFcn(app, @importEngineParamsFromKsp_Callback, true);
            app.importEngineParamsFromKsp.Text = 'Import Engine Parameters from KSP Engine File';
            app.importEngineParamsFromKsp.Tag = 'importEngineParamsFromKsp';
            % Assign app.throttleThrustIspContextMenu
            app.uipanel1.ContextMenu = app.throttleThrustIspContextMenu;
            app.editThrustProfileButton.ContextMenu = app.throttleThrustIspContextMenu;
            app.editIspProfileButton.ContextMenu = app.throttleThrustIspContextMenu;
            app.text15.ContextMenu = app.throttleThrustIspContextMenu;
            app.text14.ContextMenu = app.throttleThrustIspContextMenu;
            app.minThrottleText.ContextMenu = app.throttleThrustIspContextMenu;
            app.text17.ContextMenu = app.throttleThrustIspContextMenu;
            app.maxThrottleText.ContextMenu = app.throttleThrustIspContextMenu;
            app.text16.ContextMenu = app.throttleThrustIspContextMenu;
            % Show the figure after all components are created
            app.lvd_EditEngineGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditEngineGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditEngineGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditEngineGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditEngineGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditEngineGUI)
        end
    end
end