classdef lvd_EditEngineToTanksConnGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditEngineToTanksConnGUI  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        uipanel1                      matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        tankCombo                     matlab.ui.control.DropDown
        engineCombo                   matlab.ui.control.DropDown
        text13                        matlab.ui.control.Label
        stageName                     matlab.ui.control.Label
        GridLayout2                   matlab.ui.container.GridLayout
        cancelButton                  matlab.ui.control.Button
        saveAndCloseButton            matlab.ui.control.Button
        text2                         matlab.ui.control.Label
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, e2TConn)
            lv = e2TConn.engine.stage.launchVehicle;

            [tanksListStr, ~] = lv.getTanksListBoxStr();
            indTank = lv.getListBoxIndForTank(e2TConn.tank);
            if(isempty(indTank))
                indTank = 1;
            end

            [enginesListStr, ~] = lv.getEnginesListBoxStr();
            indEngine = lv.getListBoxIndForEngine(e2TConn.engine);
            if(isempty(indEngine))
                indEngine = 1;
            end

            set(handles.tankCombo,'String',tanksListStr);
            set(handles.tankCombo,'Value',indTank);
            set(handles.engineCombo,'String',enginesListStr);
            set(handles.engineCombo,'Value',indEngine);
        end

        
        function saveAndClose(app, handles)
            e2TConn = getappdata(handles.lvd_EditEngineToTanksConnGUI, 'e2TConn');
            lv = e2TConn.engine.stage.launchVehicle;
            
            tank = lv.getTankForInd(handles.tankCombo.Value);
            engine = lv.getEngineForInd(handles.engineCombo.Value);
            
            e2TConn.tank = tank;
            e2TConn.engine = engine;
            
            app.output.output = {true};
            close(handles.lvd_EditEngineToTanksConnGUI);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditEngineToTanksConnGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditEngineToTanksConnGUI (see VARARGIN)

            % Choose default command line output for lvd_EditEngineToTanksConnGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            e2TConn = varargin{1};
            setappdata(hObject, 'e2TConn', e2TConn);
            
            app.output = varargin{2};

            populateGUI(app, handles, e2TConn);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditEngineToTanksConnGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditEngineToTanksConnGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditEngineToTanksConnGUI);
        end

        % Window key press function: lvd_EditEngineToTanksConnGUI
        function lvd_EditEngineToTanksConnGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditEngineToTanksConnGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditEngineToTanksConnGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.saveAndClose(handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditEngineToTanksConnGUI = uifigure('Visible', 'off');
            app.lvd_EditEngineToTanksConnGUI.Name = 'Edit Engine To Tank Connection';
            app.lvd_EditEngineToTanksConnGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditEngineToTanksConnGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditEngineToTanksConnGUI_WindowKeyPressFcn, true);
            app.lvd_EditEngineToTanksConnGUI.Tag = 'lvd_EditEngineToTanksConnGUI';
            app.GridLayout = uigridlayout(app.lvd_EditEngineToTanksConnGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Engine To Tank Connection';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.stageName = uilabel(app.GridLayout3);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Connected Engine';
            app.text13 = uilabel(app.GridLayout3);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Connected Tank';
            app.engineCombo = uidropdown(app.GridLayout3);
            app.engineCombo.Items = {'ENGINE NAME'};
            app.engineCombo.Tag = 'engineCombo';
            app.engineCombo.Value = 'ENGINE NAME';
            app.tankCombo = uidropdown(app.GridLayout3);
            app.tankCombo.Items = {'Pop-up Menu'};
            app.tankCombo.Tag = 'tankCombo';
            app.tankCombo.Value = 'Pop-up Menu';
            % Show the figure after all components are created
            app.lvd_EditEngineToTanksConnGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditEngineToTanksConnGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditEngineToTanksConnGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditEngineToTanksConnGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditEngineToTanksConnGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditEngineToTanksConnGUI)
        end
    end
end