classdef lvd_EditEngineToTanksConnsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditEngineToTanksConnsGUI  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        removeConnButton               matlab.ui.control.Button
        addConnButton                  matlab.ui.control.Button
        connsListBox                   matlab.ui.control.ListBox
        closeButton                    matlab.ui.control.Button
        text2                          matlab.ui.control.Label
    end

    methods (Access = private)
        function populateGUI(app, ~, ~)
            [~, ~] = app.setListboxStr();
        end
        
        function [e2TConnStr, e2TConns] = setListboxStr(app)
            lvdData = getappdata(app.lvd_EditEngineToTanksConnsGUI,'lvdData');
            [e2TConnStr, e2TConns] = lvdData.launchVehicle.getEngineToTankConnectionsListBoxStr();
            app.connsListBox.Items = e2TConnStr;
            app.connsListBox.ItemsData = e2TConns;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditEngineToTanksConnsGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditEngineToTanksConnsGUI (see VARARGIN)

            % Choose default command line output for lvd_EditEngineToTanksConnsGUI
            handles.output = hObject;

            centerUIFigure(app.lvd_EditEngineToTanksConnsGUI);
            applySelectedThemeToApp(app);

            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            populateGUI(app, handles, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditEngineToTanksConnsGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditEngineToTanksConnsGUI);
        end

        % Button pushed function: addConnButton
        function addConnButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addConnButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditEngineToTanksConnsGUI,'lvdData');

            tempEngine = lvdData.launchVehicle.getEngineForInd(1);
            tempTank = lvdData.launchVehicle.getTankForInd(1);

            e2TConn = EngineToTankConnection(tempTank, tempEngine);
            
            output = AppDesignerGUIOutput({false});
            lvd_EditEngineToTanksConnGUI_App(e2TConn, output);
            useE2TConn = output.output{1};

            if(useE2TConn)
                lvdData.launchVehicle.addEngineToTankConnection(e2TConn);

                lvState = lvdData.initStateModel.lvState;
                connState = EngineToTankConnState(e2TConn);
                lvState.addE2TConnState(connState);

                [~, ~] = app.setListboxStr();
                app.connsListBox.Value = e2TConn;
            end
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to closeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditEngineToTanksConnsGUI);
        end

        % Value changed function: connsListBox
        function connsListBox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to connsListBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns connsListBox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from connsListBox
            %                 if(strcmpi(get(handles.lvd_EditEngineToTanksConnsGUI,'SelectionType'),'open'))
            %                     lvdData = getappdata(handles.lvd_EditEngineToTanksConnsGUI,'lvdData');
            %                     lv = lvdData.launchVehicle;
            %
            %                     selE2TConn = get(handles.connsListBox,'Value');
            %                     e2TConn = lv.getEngineToTankForInd(selE2TConn);
            %
            %                     lvd_EditEngineToTanksConnGUI(e2TConn);
            %                     set(handles.connsListBox,'String',lvdData.launchVehicle.getEngineToTankConnectionsListBoxStr());
            %                 end
        end

        % Window key press function: lvd_EditEngineToTanksConnsGUI
        function lvd_EditEngineToTanksConnsGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditEngineToTanksConnsGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            switch(eventdata.Key)
                case 'return'
                    close(handles.lvd_EditEngineToTanksConnsGUI);
                case 'enter'
                    close(handles.lvd_EditEngineToTanksConnsGUI);
                case 'escape'
                    close(handles.lvd_EditEngineToTanksConnsGUI);
            end
        end

        % Button pushed function: removeConnButton
        function removeConnButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeConnButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditEngineToTanksConnsGUI,'lvdData');
            lv = lvdData.launchVehicle;

%             selConn = get(handles.connsListBox,'Value');
%             conn = lv.getEngineToTankForInd(selConn);
            conn = app.connsListBox.Value;

            tf = conn.isInUse();

            if(tf == false)
                lvState = lvdData.initStateModel.lvState;
                lvState.removeE2TConnStateForConn(conn);

                lv.removeEngineToTankConnection(conn);

%                 listBoxStr = lvdData.launchVehicle.getEngineToTankConnectionsListBoxStr();

%                 numConns = length(listBoxStr);
%                 if(selConn > numConns)
%                     set(handles.connsListBox,'Value',numConns);
%                 end

                [~, ~] = app.setListboxStr();
            else
                msg = sprintf('Could not delete the "%s" connection because it is in use as part of an event termination condition, event action, objective function, or constraint.  Remove the dependencies before attempting to delete the connection.', conn.getName());
%                 warndlg(msg,'Cannot Delete Connection','modal');
                uialert(app.lvd_EditEngineToTanksConnsGUI, msg, 'Cannot Delete Connection',"Modal",true);
            end
        end

        % Window button down function: lvd_EditEngineToTanksConnsGUI
        function lvd_EditEngineToTanksConnsGUIWindowButtonDown(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            selType = app.lvd_EditEngineToTanksConnsGUI.SelectionType;
            curObj = app.lvd_EditEngineToTanksConnsGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.connsListBox && numel(app.connsListBox.ItemsData) > 0)
                    lvdData = getappdata(handles.lvd_EditEngineToTanksConnsGUI,'lvdData');
                    lv = lvdData.launchVehicle;

%                     selE2TConn = get(handles.connsListBox,'Value');
%                     e2TConn = lv.getEngineToTankForInd(selE2TConn);
                    e2TConn = app.connsListBox.Value;
                    
                    if(not(isempty(e2TConn)))
%                         lvd_EditEngineToTanksConnGUI(e2TConn);
                        output = AppDesignerGUIOutput({false});
                        lvd_EditEngineToTanksConnGUI_App(e2TConn, output);
                        
                        [~, ~] = app.setListboxStr();
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditEngineToTanksConnsGUI = uifigure('Visible', 'off');
            app.lvd_EditEngineToTanksConnsGUI.Name = 'Edit Engine To Tank Connections';
            app.lvd_EditEngineToTanksConnsGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditEngineToTanksConnsGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_EditEngineToTanksConnsGUIWindowButtonDown, true);
            app.lvd_EditEngineToTanksConnsGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditEngineToTanksConnsGUI_WindowKeyPressFcn, true);
            app.lvd_EditEngineToTanksConnsGUI.Tag = 'lvd_EditEngineToTanksConnsGUI';
            app.GridLayout = uigridlayout(app.lvd_EditEngineToTanksConnsGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Engine to Tank Connections';
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Icon = 'close_window(1).png';
            app.closeButton.Text = 'Close';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.connsListBox = uilistbox(app.GridLayout2);
            app.connsListBox.Items = {'Listbox'};
            app.connsListBox.ValueChangedFcn = createCallbackFcn(app, @connsListBox_Callback, true);
            app.connsListBox.Tag = 'connsListBox';
            app.connsListBox.Value = 'Listbox';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.addConnButton = uibutton(app.GridLayout3, 'push');
            app.addConnButton.ButtonPushedFcn = createCallbackFcn(app, @addConnButton_Callback, true);
            app.addConnButton.Tag = 'addConnButton';
            app.addConnButton.Icon = 'add.png';
            app.addConnButton.Text = 'Add Connection';
            app.removeConnButton = uibutton(app.GridLayout3, 'push');
            app.removeConnButton.ButtonPushedFcn = createCallbackFcn(app, @removeConnButton_Callback, true);
            app.removeConnButton.Tag = 'removeConnButton';
            app.removeConnButton.Icon = 'remove.png';
            app.removeConnButton.Text = 'Remove Connection';
            % Show the figure after all components are created
            app.lvd_EditEngineToTanksConnsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditEngineToTanksConnsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditEngineToTanksConnsGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditEngineToTanksConnsGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditEngineToTanksConnsGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditEngineToTanksConnsGUI)
        end
    end
end