classdef lvd_EditEnginesGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditEnginesGUI         matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        Panel                      matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        GridLayout3                matlab.ui.container.GridLayout
        removeEngineButton         matlab.ui.control.Button
        addEngineButton            matlab.ui.control.Button
        enginesListBox             matlab.ui.control.ListBox
        closeButton                matlab.ui.control.Button
        text2                      matlab.ui.control.Label
        enginesListboxContextMenu  matlab.ui.container.ContextMenu
        copyEngineMenu             matlab.ui.container.Menu
    end

    methods (Access = private)
        function stageState = getStageStateForEngine(~, engine, lvdData)
            stage = engine.stage;
            stageStates = lvdData.initStateModel.stageStates;
            stageStateInd = find([stageStates.stage] == stage,1,'first');
            stageState = stageStates(stageStateInd);
        end

        function populateGUI(app, ~, ~)
            [~, ~] = app.setListboxStr();
        end
        
        function [enginesListStr, engines] = setListboxStr(app)
            lvdData = getappdata(app.lvd_EditEnginesGUI,'lvdData');
            [enginesListStr, engines] = lvdData.launchVehicle.getEnginesListBoxStr();
            app.enginesListBox.Items = enginesListStr;
            app.enginesListBox.ItemsData = engines;
            
            num = numel(engines);
            if(num <= 1)
                app.removeEngineButton.Enable = 'off';
            else
                app.removeEngineButton.Enable = 'on';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditEnginesGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditEnginesGUI (see VARARGIN)

            % Choose default command line output for lvd_EditEnginesGUI
            handles.output = hObject;
            
            centerUIFigure(app.lvd_EditEnginesGUI);
            applySelectedThemeToApp(app);

            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            populateGUI(app, handles, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditEnginesGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditEnginesGUI);
        end

        % Button pushed function: addEngineButton
        function addEngineButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addEngineButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditEnginesGUI,'lvdData');

            initStage = lvdData.launchVehicle.getStageForInd(1);

            engine = LaunchVehicleEngine(initStage);
%             useEngine = lvd_EditEngineGUI(engine);

            output = AppDesignerGUIOutput({false});
            lvd_EditEngineGUI_App(engine, output);
            useEngine = output.output{1};

            if(useEngine)
                engine.stage.addEngine(engine);
                
                stageStates = lvdData.initStateModel.stageStates;
                stageStateInd = find([stageStates.stage] == engine.stage,1,'first');
                stageState = stageStates(stageStateInd);

                newEngineState = LaunchVehicleEngineState(stageState);
                newEngineState.engine = engine;
                stageState.addEngineState(newEngineState);

                [~, ~] = app.setListboxStr();
            end
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to closeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditEnginesGUI);
        end

        % Menu selected function: copyEngineMenu
        function copyEngineMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to copyEngineMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditEnginesGUI,'lvdData');
%             lv = lvdData.launchVehicle;

            engine = app.enginesListBox.Value;

            newEngine = engine.copy();
            initStage = newEngine.stage;

            newEngine.stage.addEngine(newEngine);

            stageStates = lvdData.initStateModel.stageStates;
            stageStateInd = find([stageStates.stage] == initStage,1,'first');
            stageState = stageStates(stageStateInd);

            newEngineState = LaunchVehicleEngineState(stageState);
            newEngineState.engine = newEngine;
            stageState.addEngineState(newEngineState);

            [~, ~] = app.setListboxStr();
        end

        % Value changed function: enginesListBox
        function enginesListBox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to enginesListBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns enginesListBox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from enginesListBox
%             if(strcmpi(get(handles.lvd_EditEnginesGUI,'SelectionType'),'open'))
%                 lvdData = getappdata(handles.lvd_EditEnginesGUI,'lvdData');
%                 lv = lvdData.launchVehicle;
% 
%                 selEngine = get(handles.enginesListBox,'Value');
%                 engine = lv.getEngineForInd(selEngine);
%                 stageState = getStageStateForEngine(app, engine, lvdData);
%                 oldEngineState = stageState.getStateForEngine(engine);
%                 stageState.removeEngineStateForEngine(engine);
% 
%                 lvd_EditEngineGUI(engine);
% 
%                 stageState = getStageStateForEngine(app, engine, lvdData);
%                 newEngineState = LaunchVehicleEngineState(stageState);
%                 newEngineState.engine = engine;
% 
%                 if(not(isempty(oldEngineState)))
%                     newEngineState.active = oldEngineState.active;
%                 end
%                 stageState.addEngineState(newEngineState);
% 
%                 set(handles.enginesListBox,'String',lvdData.launchVehicle.getEnginesListBoxStr());
%             end
        end

        % Window key press function: lvd_EditEnginesGUI
        function lvd_EditEnginesGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditEnginesGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            switch(eventdata.Key)
                case 'return'
                    close(handles.lvd_EditEnginesGUI);
                case 'enter'
                    close(handles.lvd_EditEnginesGUI);
                case 'escape'
                    close(handles.lvd_EditEnginesGUI);
            end
        end

        % Button pushed function: removeEngineButton
        function removeEngineButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeEngineButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditEnginesGUI,'lvdData');
            lv = lvdData.launchVehicle;

            engine = app.enginesListBox.Value;

            tf = engine.isInUse();

            if(tf == false)
                stage = engine.stage;

                lv.removeAllEngineToTanksConnsWithEngine(engine);

                stageStates = lvdData.initStateModel.stageStates;
                stageStateInd = find([stageStates.stage] == stage,1,'first');
                stageState = stageStates(stageStateInd);

                stageState.removeEngineStateForEngine(engine);

                stage.removeEngine(engine);

                [~, ~] = app.setListboxStr();
            else
                msg = sprintf('Could not delete the engine "%s" because it is in use as part of an event termination condition, event action, objective function, or constraint.  Remove the engine dependencies before attempting to delete the engine.', engine.name);
%                 warndlg(msg,'Cannot Delete Engine','modal');
                uialert(app.lvd_EditEnginesGUI, msg, 'Cannot Delete Engine',"Modal",true);
            end
        end

        % Window button down function: lvd_EditEnginesGUI
        function lvd_EditEnginesGUIWindowButtonDown(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            selType = app.lvd_EditEnginesGUI.SelectionType;
            curObj = app.lvd_EditEnginesGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.enginesListBox && numel(app.enginesListBox.ItemsData) > 0)
                    lvdData = getappdata(handles.lvd_EditEnginesGUI,'lvdData');
%                     lv = lvdData.launchVehicle;
    
                    engine = app.enginesListBox.Value;
                    
                    stageState = getStageStateForEngine(app, engine, lvdData);
                    oldEngineState = stageState.getStateForEngine(engine);
                    stageState.removeEngineStateForEngine(engine);
    
%                     lvd_EditEngineGUI(engine);
                    output = AppDesignerGUIOutput({false});
                    lvd_EditEngineGUI_App(engine, output);  
    
                    stageState = getStageStateForEngine(app, engine, lvdData);
                    newEngineState = LaunchVehicleEngineState(stageState);
                    newEngineState.engine = engine;
    
                    if(not(isempty(oldEngineState)))
                        newEngineState.active = oldEngineState.active;
                    end
                    stageState.addEngineState(newEngineState);
    
                    [~, ~] = app.setListboxStr();
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditEnginesGUI = uifigure('Visible', 'off');
            app.lvd_EditEnginesGUI.Name = 'Edit Engines';
            app.lvd_EditEnginesGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditEnginesGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_EditEnginesGUIWindowButtonDown, true);
            app.lvd_EditEnginesGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditEnginesGUI_WindowKeyPressFcn, true);
            app.lvd_EditEnginesGUI.Tag = 'lvd_EditEnginesGUI';
            app.GridLayout = uigridlayout(app.lvd_EditEnginesGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Engines';
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Icon = 'close_window(1).png';
            app.closeButton.Text = 'Close';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.enginesListBox = uilistbox(app.GridLayout2);
            app.enginesListBox.Items = {'Listbox'};
            app.enginesListBox.ValueChangedFcn = createCallbackFcn(app, @enginesListBox_Callback, true);
            app.enginesListBox.Tag = 'enginesListBox';
            app.enginesListBox.Value = 'Listbox';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.addEngineButton = uibutton(app.GridLayout3, 'push');
            app.addEngineButton.ButtonPushedFcn = createCallbackFcn(app, @addEngineButton_Callback, true);
            app.addEngineButton.Tag = 'addEngineButton';
            app.addEngineButton.Icon = 'add.png';
            app.addEngineButton.Text = 'Add Engine';
            app.removeEngineButton = uibutton(app.GridLayout3, 'push');
            app.removeEngineButton.ButtonPushedFcn = createCallbackFcn(app, @removeEngineButton_Callback, true);
            app.removeEngineButton.Tag = 'removeEngineButton';
            app.removeEngineButton.Icon = 'remove.png';
            app.removeEngineButton.Text = 'Remove Engine';
            app.enginesListboxContextMenu = uicontextmenu(app.lvd_EditEnginesGUI);
            app.enginesListboxContextMenu.Tag = 'enginesListboxContextMenu';
            app.copyEngineMenu = uimenu(app.enginesListboxContextMenu);
            app.copyEngineMenu.MenuSelectedFcn = createCallbackFcn(app, @copyEngineMenu_Callback, true);
            app.copyEngineMenu.Text = 'Create Copy of Selected Engine';
            app.copyEngineMenu.Tag = 'copyEngineMenu';
            % Assign app.enginesListboxContextMenu
            app.enginesListBox.ContextMenu = app.enginesListboxContextMenu;
            % Show the figure after all components are created
            app.lvd_EditEnginesGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditEnginesGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditEnginesGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditEnginesGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditEnginesGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditEnginesGUI)
        end
    end
end