classdef lvd_EditExtremaGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditExtremaGUI     matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        uipanel1               matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        uipanel2               matlab.ui.container.Panel
        GridLayout7            matlab.ui.container.GridLayout
        GridLayout9            matlab.ui.container.GridLayout
        setFrameOptionsButton  matlab.ui.control.Button
        GridLayout8            matlab.ui.container.GridLayout
        refFrameTypeCombo      matlab.ui.control.DropDown
        text18                 matlab.ui.control.Label
        refFrameNameLabel      matlab.ui.control.Label
        GridLayout6            matlab.ui.container.GridLayout
        startRecordingCombo    matlab.ui.control.DropDown
        text7                  matlab.ui.control.Label
        GridLayout5            matlab.ui.container.GridLayout
        typeCombo              matlab.ui.control.DropDown
        text5                  matlab.ui.control.Label
        GridLayout4            matlab.ui.container.GridLayout
        extremaQuantCombo      matlab.ui.control.DropDown
        extremaQuantLabel      matlab.ui.control.Label
        GridLayout2            matlab.ui.container.GridLayout
        cancelButton           matlab.ui.control.Button
        saveAndCloseButton     matlab.ui.control.Button
        text2                  matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function bodyInfo = getSelectedBodyInfo(app, handles)
            curFrame = getappdata(handles.lvd_EditExtremaGUI,'frame');
            bodyInfo = curFrame.getOriginBody();
        end

        function populateGUI(app, handles, extrema, lvdData)
            exclude = getLvdGAExcludeList();
            exclude{end+1} = 'Distance Traveled';
            [extremaGAStr, ~] = lvdData.launchVehicle.getExtremaGraphAnalysisTaskStrs();
            exclude = horzcat(exclude,extremaGAStr);
            taskList = lvd_getGraphAnalysisTaskList(lvdData, exclude);

            handles.extremaQuantCombo.String = taskList;
            if(isempty(extrema.quantStr))
                value = 1;
            else
                value = findValueFromComboBox(extrema.quantStr, handles.extremaQuantCombo);
            end
            handles.extremaQuantCombo.Value = value;

            handles.typeCombo.String = LaunchVehicleExtremaTypeEnum.getNameStrs();
            handles.typeCombo.Value = LaunchVehicleExtremaTypeEnum.getIndOfListboxStrs(extrema.type);

            %     populateBodiesCombo(lvdData.celBodyData, handles.refFrameTypeCombo);
            %     if(isempty(extrema.refBody))
            %         value = 1;
            %     else
            %         value = findValueFromComboBox(extrema.refBody.name, handles.refFrameTypeCombo);
            %     end
            %     handles.refFrameTypeCombo.Value = value;

            frame = extrema.frame;
            if(isempty(frame))
                frame = LvdData.getDefaultInitialBodyInfo(lvdData.celBodyData).getBodyCenteredInertialFrame();
                extrema.frame = frame;
            end
            setappdata(handles.lvd_EditExtremaGUI,'frame',frame);

            handles.refFrameTypeCombo.String = ReferenceFrameEnum.getListBoxStr();
            [ind, ~] = ReferenceFrameEnum.getIndForName(frame.typeEnum.name);
            handles.refFrameTypeCombo.Value = ind;
            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', frame.getNameStr());
            handles.refFrameNameLabel.String = sprintf('%s', frame.getNameStr());
            handles.refFrameNameLabel.TooltipString = sprintf('%s', frame.getNameStr());

            handles.startRecordingCombo.String = LaunchVehicleExtremaRecordingEnum.getNameStrs();
            handles.startRecordingCombo.Value = LaunchVehicleExtremaRecordingEnum.getIndOfListboxStrs(extrema.startingState);
        end

        function updateFrameChange(app, handles)
            lvdData = getappdata(handles.lvd_EditExtremaGUI,'lvdData');
            celBodyData = lvdData.celBodyData;

            refEnumListBoxStr = ReferenceFrameEnum.getListBoxStr();
            selFrameTypeInd = handles.refFrameTypeCombo.Value;
            refFrameEnum = ReferenceFrameEnum.getEnumForListboxStr(refEnumListBoxStr{selFrameTypeInd});

            %             if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
            %                 refFrameEnum = newFrame.typeEnum;
            %                 app.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
            %             end

            switch refFrameEnum
                case ReferenceFrameEnum.BodyCenteredInertial
                    bodyInfo = getSelectedBodyInfo(app, handles);

                    newFrame = bodyInfo.getBodyCenteredInertialFrame();

                case ReferenceFrameEnum.BodyFixedRotating
                    bodyInfo = getSelectedBodyInfo(app, handles);

                    newFrame = bodyInfo.getBodyFixedFrame();

                case ReferenceFrameEnum.TwoBodyRotating
                    bodyInfo = getSelectedBodyInfo(app, handles);
                    if(not(isempty(bodyInfo.childrenBodyInfo)))
                        primaryBody = bodyInfo;
                        secondaryBody = bodyInfo.childrenBodyInfo(1);
                    else
                        primaryBody = bodyInfo.getParBodyInfo();
                        secondaryBody = bodyInfo;
                    end

                    originPt = TwoBodyRotatingFrameOriginEnum.Primary;

                    newFrame = TwoBodyRotatingFrame(primaryBody, secondaryBody, originPt, celBodyData);

                case ReferenceFrameEnum.UserDefined
                    numFrames = lvdData.geometry.refFrames.getNumRefFrames();
                    if(numFrames >= 1)
                        geometricFrame = AbstractGeometricRefFrame.empty(1,0);
                        for(i=1:numFrames)
                            frame = lvdData.geometry.refFrames.getRefFrameAtInd(i);
                            if(frame.isVehDependent() == false)
                                geometricFrame = frame;
                                break;
                            end
                        end

                        if(not(isempty(geometricFrame)))
                            newFrame = UserDefinedGeometricFrame(geometricFrame, lvdData);
                        else
                            bodyInfo = getSelectedBodyInfo(app, handles);
                            newFrame = bodyInfo.getBodyCenteredInertialFrame();

                            warndlg('There are no geometric frames available which are not dependent on vehicle properties.  A body-centered inertial frame will be selected instead.');
                        end
                    else
                        bodyInfo = getSelectedBodyInfo(app, handles);
                        newFrame = bodyInfo.getBodyCenteredInertialFrame();

                        warndlg('There are no geometric frames available which are not dependent on vehicle properties.  A body-centered inertial frame will be selected instead.');
                    end

                otherwise
                    error('Unknown reference frame type: %s', string(refFrameEnum));
            end

            if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
                refFrameEnum = newFrame.typeEnum;
                handles.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
            end

            setappdata(handles.lvd_EditExtremaGUI,'frame', newFrame);
            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', newFrame.getNameStr());
            handles.refFrameNameLabel.String = sprintf('%s', newFrame.getNameStr());
            handles.refFrameNameLabel.TooltipString = sprintf('%s', newFrame.getNameStr());
        end


        function saveAndClose(app, handles)
            extremum = getappdata(handles.lvd_EditExtremaGUI, 'extrema');
            %             lvdData = getappdata(hObject, 'lvdData');

            quantityStrs = handles.extremaQuantCombo.String;
            quantity = strtrim(quantityStrs{handles.extremaQuantCombo.Value});
            extremum.quantStr = quantity;

            m = enumeration('LaunchVehicleExtremaTypeEnum');
            mI = m(handles.typeCombo.Value);
            extremum.type = mI;

            frame = getappdata(handles.lvd_EditExtremaGUI,'frame');
            extremum.frame = frame;

            m = enumeration('LaunchVehicleExtremaRecordingEnum');
            mI = m(handles.startRecordingCombo.Value);
            extremum.startingState = mI;

            app.output.output = {true};
            close(handles.lvd_EditExtremaGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditExtremaGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditExtremaGUI (see VARARGIN)

            % Choose default command line output for lvd_EditExtremaGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            extrema = varargin{1};
            setappdata(hObject, 'extrema', extrema);

            lvdData = varargin{2};
            setappdata(hObject, 'lvdData', lvdData);

            app.output = varargin{3};

            populateGUI(app, handles, extrema, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditExtremaGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditExtremaGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditExtremaGUI);
        end

        % Window key press function: lvd_EditExtremaGUI
        function lvd_EditExtremaGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditExtremaGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditExtremaGUI);
            end
        end

        % Value changed function: refFrameTypeCombo
        function refFrameTypeCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to refFrameTypeCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns refFrameTypeCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from refFrameTypeCombo
            updateFrameChange(app, handles);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.saveAndClose(handles);
        end

        % Button pushed function: setFrameOptionsButton
        function setFrameOptionsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to setFrameOptionsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            curFrame = getappdata(handles.lvd_EditExtremaGUI,'frame');
            curFrame = curFrame.editFrameDialogUI(EditReferenceFrameContextEnum.ForState);

            setappdata(handles.lvd_EditExtremaGUI,'frame',curFrame);

            handles.setFrameOptionsButton.Tooltip = sprintf('Current Frame: %s', curFrame.getNameStr());
            handles.refFrameNameLabel.String = sprintf('%s', curFrame.getNameStr());
            handles.refFrameNameLabel.TooltipString = sprintf('%s', curFrame.getNameStr());
        end

        % Value changed function: typeCombo
        function typeCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to typeCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of typeCombo as text
            %        str2double(get(hObject,'String')) returns contents of typeCombo as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditExtremaGUI = uifigure('Visible', 'off');
            app.lvd_EditExtremaGUI.Name = 'Edit Extrema';
            app.lvd_EditExtremaGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditExtremaGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditExtremaGUI_WindowKeyPressFcn, true);
            app.lvd_EditExtremaGUI.Tag = 'lvd_EditExtremaGUI';
            app.GridLayout = uigridlayout(app.lvd_EditExtremaGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Extremum';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.extremaQuantLabel = uilabel(app.GridLayout4);
            app.extremaQuantLabel.Tag = 'extremaQuantLabel';
            app.extremaQuantLabel.Text = 'Extrema Quantity';
            app.extremaQuantCombo = uidropdown(app.GridLayout4);
            app.extremaQuantCombo.Items = {'ENGINE NAME'};
            app.extremaQuantCombo.Tag = 'extremaQuantCombo';
            app.extremaQuantCombo.Value = 'ENGINE NAME';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.text5 = uilabel(app.GridLayout5);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Extrema Type';
            app.typeCombo = uidropdown(app.GridLayout5);
            app.typeCombo.Items = {'0'};
            app.typeCombo.ValueChangedFcn = createCallbackFcn(app, @typeCombo_Callback, true);
            app.typeCombo.Tag = 'typeCombo';
            app.typeCombo.Value = '0';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.text7 = uilabel(app.GridLayout6);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Start Recording?';
            app.startRecordingCombo = uidropdown(app.GridLayout6);
            app.startRecordingCombo.Items = {'False', 'True'};
            app.startRecordingCombo.Tag = 'startRecordingCombo';
            app.startRecordingCombo.Value = 'False';
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Reference Frame';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout7 = uigridlayout(app.uipanel2);
            app.refFrameNameLabel = uilabel(app.GridLayout7);
            app.refFrameNameLabel.Tag = 'refFrameNameLabel';
            app.refFrameNameLabel.Text = 'Static Text';
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.text18 = uilabel(app.GridLayout8);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Frame Type';
            app.refFrameTypeCombo = uidropdown(app.GridLayout8);
            app.refFrameTypeCombo.Items = {'False', 'True'};
            app.refFrameTypeCombo.ValueChangedFcn = createCallbackFcn(app, @refFrameTypeCombo_Callback, true);
            app.refFrameTypeCombo.Tag = 'refFrameTypeCombo';
            app.refFrameTypeCombo.Value = 'False';
            app.GridLayout9 = uigridlayout(app.GridLayout7);
            app.setFrameOptionsButton = uibutton(app.GridLayout9, 'push');
            app.setFrameOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @setFrameOptionsButton_Callback, true);
            app.setFrameOptionsButton.Tag = 'setFrameOptionsButton';
            app.setFrameOptionsButton.Icon = 'reference_frame.png';
            app.setFrameOptionsButton.Tooltip = 'Sets parameters for the selected reference frame type.';
            app.setFrameOptionsButton.Text = 'Set Frame Options';
            % Show the figure after all components are created
            app.lvd_EditExtremaGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditExtremaGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditExtremaGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditExtremaGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditExtremaGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditExtremaGUI)
        end
    end
end