classdef lvd_EditExtremasGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditExtremasGUI  matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        Panel                matlab.ui.container.Panel
        GridLayout2          matlab.ui.container.GridLayout
        GridLayout3          matlab.ui.container.GridLayout
        removeExtremaButton  matlab.ui.control.Button
        addExtremaButton     matlab.ui.control.Button
        extremasListBox      matlab.ui.control.ListBox
        closeButton          matlab.ui.control.Button
        text2                matlab.ui.control.Label
    end


    methods (Access = private)
        function populateGUI(app, ~, ~)
            [~, ~] = app.setListboxStr();
        end
        
        function [exListStr, extrema] = setListboxStr(app)
            lvdData = getappdata(app.lvd_EditExtremasGUI,'lvdData');
            [exListStr, extrema] = lvdData.launchVehicle.getExtremaListBoxStr();
            app.extremasListBox.Items = exListStr;
            app.extremasListBox.ItemsData = extrema;
            
            numExtrema = numel(extrema);
            if(numExtrema <= 0)
                app.removeExtremaButton.Enable = 'off';
            else
                app.removeExtremaButton.Enable = 'on';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditExtremasGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditExtremasGUI (see VARARGIN)

            % Choose default command line output for lvd_EditExtremasGUI
            handles.output = hObject;
            
            centerUIFigure(app.lvd_EditExtremasGUI);
            applySelectedThemeToApp(app);

            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            populateGUI(app, handles, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditExtremasGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditExtremasGUI);
        end

        % Button pushed function: addExtremaButton
        function addExtremaButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addExtremaButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditExtremasGUI,'lvdData');

            extremum = LaunchVehicleExtrema(lvdData);
%             useExtremum = lvd_EditExtremaGUI(extremum, lvdData);

            output = AppDesignerGUIOutput({false});
            lvd_EditExtremaGUI_App(extremum, lvdData, output);
            useExtremum = output.output{1};

            if(useExtremum)
                lvdData.launchVehicle.addExtremum(extremum);

                [~, ~] = app.setListboxStr();
                app.extremasListBox.Value = extremum;
            end
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to closeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditExtremasGUI);
        end

        % Value changed function: extremasListBox
        function extremasListBox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to extremasListBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns extremasListBox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from extremasListBox
%             if(strcmpi(get(handles.lvd_EditExtremasGUI,'SelectionType'),'open'))
%                 lvdData = getappdata(handles.lvd_EditExtremasGUI,'lvdData');
%                 lv = lvdData.launchVehicle;
% 
%                 selEx = get(handles.extremasListBox,'Value');
%                 extremum = lv.getExtremaForInd(selEx);
% 
%                 lvd_EditExtremaGUI(extremum, lvdData);
% 
%                 set(handles.extremasListBox,'String',lvdData.launchVehicle.getExtremaListBoxStr());
%             end
        end

        % Window key press function: lvd_EditExtremasGUI
        function lvd_EditExtremasGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditExtremasGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            switch(eventdata.Key)
                case 'return'
                    close(handles.lvd_EditExtremasGUI);
                case 'enter'
                    close(handles.lvd_EditExtremasGUI);
                case 'escape'
                    close(handles.lvd_EditExtremasGUI);
            end
        end

        % Button pushed function: removeExtremaButton
        function removeExtremaButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeExtremaButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditExtremasGUI,'lvdData');
            lv = lvdData.launchVehicle;

            extremum = app.extremasListBox.Value;

            tf = extremum.isInUse();

            if(tf == false)
                lv.removeExtremum(extremum);

%                 numExa = length(handles.extremasListBox.String);
%                 if(selEx > numExa)
%                     set(handles.extremasListBox,'Value',numExa);
%                 end
                
                [~, ~] = app.setListboxStr();
            else
                msg = sprintf('Could not delete the extremum "%s" because it is in use as part of an event termination condition, event action, objective function, or constraint.  Remove these extremum dependencies before attempting to delete the extremum.', extremum.getNameStr());
%                 warndlg(msg,'Cannot Delete Extremum','modal');
                uialert(app.lvd_EditExtremasGUI, msg, 'Cannot Delete Extremum',"Modal",true);
            end
        end

        % Window button down function: lvd_EditExtremasGUI
        function lvd_EditExtremasGUIWindowButtonDown(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            selType = app.lvd_EditExtremasGUI.SelectionType;
            curObj = app.lvd_EditExtremasGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.extremasListBox && numel(app.extremasListBox.ItemsData) > 0)
                    lvdData = getappdata(handles.lvd_EditExtremasGUI,'lvdData');
    
                    extremum = app.extremasListBox.Value;
    
%                     lvd_EditExtremaGUI(extremum, lvdData);
                    output = AppDesignerGUIOutput({false});
                    lvd_EditExtremaGUI_App(extremum, lvdData, output);
    
                    [~, ~] = app.setListboxStr();
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditExtremasGUI = uifigure('Visible', 'off');
            app.lvd_EditExtremasGUI.Name = 'Edit Extrema';
            app.lvd_EditExtremasGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditExtremasGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_EditExtremasGUIWindowButtonDown, true);
            app.lvd_EditExtremasGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditExtremasGUI_WindowKeyPressFcn, true);
            app.lvd_EditExtremasGUI.Tag = 'lvd_EditExtremasGUI';
            app.GridLayout = uigridlayout(app.lvd_EditExtremasGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Extrema';
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Icon = 'close_window(1).png';
            app.closeButton.Text = 'Close';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.extremasListBox = uilistbox(app.GridLayout2);
            app.extremasListBox.Items = {'Listbox'};
            app.extremasListBox.ValueChangedFcn = createCallbackFcn(app, @extremasListBox_Callback, true);
            app.extremasListBox.Tag = 'extremasListBox';
            app.extremasListBox.Tooltip = 'Extrema allow you to look for and record the maximum or minimum value of some quantity over the course of a flight or a segment of a flight. ';
            app.extremasListBox.Value = 'Listbox';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.addExtremaButton = uibutton(app.GridLayout3, 'push');
            app.addExtremaButton.ButtonPushedFcn = createCallbackFcn(app, @addExtremaButton_Callback, true);
            app.addExtremaButton.Tag = 'addExtremaButton';
            app.addExtremaButton.Icon = 'add.png';
            app.addExtremaButton.Text = 'Add Extremum';
            app.removeExtremaButton = uibutton(app.GridLayout3, 'push');
            app.removeExtremaButton.ButtonPushedFcn = createCallbackFcn(app, @removeExtremaButton_Callback, true);
            app.removeExtremaButton.Tag = 'removeExtremaButton';
            app.removeExtremaButton.Icon = 'remove.png';
            app.removeExtremaButton.Text = 'Remove Extremum';
            % Show the figure after all components are created
            app.lvd_EditExtremasGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditExtremasGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditExtremasGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditExtremasGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditExtremasGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditExtremasGUI)
        end
    end
end