classdef lvd_EditStageGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditStageGUI    matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        uipanel1            matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        GridLayout5         matlab.ui.container.GridLayout
        ubText              matlab.ui.control.EditField
        lbText              matlab.ui.control.EditField
        optCheckbox         matlab.ui.control.CheckBox
        text6               matlab.ui.control.Label
        dryMassText         matlab.ui.control.EditField
        text5               matlab.ui.control.Label
        GridLayout4         matlab.ui.container.GridLayout
        stageNameText       matlab.ui.control.EditField
        stageName           matlab.ui.control.Label
        GridLayout2         matlab.ui.container.GridLayout
        cancelButton        matlab.ui.control.Button
        saveAndCloseButton  matlab.ui.control.Button
        text2               matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, stage)
            set(handles.stageNameText,'String',stage.name);
            set(handles.dryMassText,'String',fullAccNum2Str(stage.getStageDryMass()));

            optVar = stage.getExistingOptVar();
            if(isempty(optVar))
                optVar = stage.getNewOptVar();
            end

            handles.optCheckbox.Value = double(optVar.getUseTfForVariable());

            [lb, ub] = optVar.getAllBndsForVariable();
            handles.lbText.String = fullAccNum2Str(lb);
            handles.ubText.String = fullAccNum2Str(ub);

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            optCheckbox_Callback(app, evt);
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            dryMass = str2double(get(handles.dryMassText,'String'));
            enteredStr = get(handles.dryMassText,'String');
            numberName = 'Dry Mass';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(dryMass, numberName, lb, ub, isInt, errMsg, enteredStr);

            lwrBnd = str2double(get(handles.lbText,'String'));
            enteredStr = get(handles.lbText,'String');
            numberName = 'Dry Mass (Lower Bound)';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(lwrBnd, numberName, lb, ub, isInt, errMsg, enteredStr);

            uprBnd = str2double(get(handles.ubText,'String'));
            enteredStr = get(handles.ubText,'String');
            numberName = 'Dry Mass (Upper Bound)';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(uprBnd, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                uprBnd = str2double(get(handles.ubText,'String'));
                enteredStr = get(handles.ubText,'String');
                numberName = 'Dry Mass (Upper Bound)';
                lb = lwrBnd;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(uprBnd, numberName, lb, ub, isInt, errMsg, enteredStr);
            end
        end


        function saveAndClose(app, handles)
            stage = getappdata(handles.lvd_EditStageGUI, 'stage');

            name = handles.stageNameText.String;
            dryMass = str2double(handles.dryMassText.String);

            stage.name = name;
            stage.dryMass = dryMass;

            optVar = stage.getExistingOptVar();
            if(not(isempty(optVar)))
                stage.launchVehicle.lvdData.optimizer.vars.removeVariable(optVar);
            end
            optVar = stage.getNewOptVar();

            optDryMass = logical(handles.optCheckbox.Value);
            lbDryMass = str2double(handles.lbText.String);
            ubDryMass = str2double(handles.ubText.String);

            optVar.setUseTfForVariable(optDryMass);
            optVar.setBndsForVariable(lbDryMass,ubDryMass);
            stage.launchVehicle.lvdData.optimizer.vars.addVariable(optVar);

            app.output.output = {true};
            close(handles.lvd_EditStageGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditStageGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditStageGUI (see VARARGIN)

            % Choose default command line output for lvd_EditStageGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            stage = varargin{1};
            setappdata(hObject, 'stage', stage);
            
            app.output = varargin{2};

            populateGUI(app, handles, stage);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditStageGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditStageGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditStageGUI);
        end

        % Value changed function: dryMassText
        function dryMassText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to dryMassText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of dryMassText as text
            %        str2double(get(hObject,'String')) returns contents of dryMassText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: lbText
        function lbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of lbText as text
            %        str2double(get(hObject,'String')) returns contents of lbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Window key press function: lvd_EditStageGUI
        function lvd_EditStageGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditStageGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditStageGUI);
            end
        end

        % Value changed function: optCheckbox
        function optCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.optCheckbox;

            % hObject    handle to optCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of optCheckbox
            if(get(hObject,'Value'))
                handles.lbText.Enable = 'on';
                handles.ubText.Enable = 'on';
            else
                handles.lbText.Enable = 'off';
                handles.ubText.Enable = 'off';
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
%                 uiresume(handles.lvd_EditStageGUI);
                app.saveAndClose(handles)
            else
%                 msgbox(errMsg,'Invalid Stage Inputs','error');
                uialert(app.lvd_EditStageGUI, errMsg, 'Invalid Stage Inputs', "Icon","error");
            end
        end

        % Value changed function: ubText
        function ubText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to ubText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of ubText as text
            %        str2double(get(hObject,'String')) returns contents of ubText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditStageGUI = uifigure('Visible', 'off');
            app.lvd_EditStageGUI.Name = 'Edit Stage';
            app.lvd_EditStageGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditStageGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditStageGUI_WindowKeyPressFcn, true);
            app.lvd_EditStageGUI.Tag = 'lvd_EditStageGUI';
            app.GridLayout = uigridlayout(app.lvd_EditStageGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Stage';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.stageName = uilabel(app.GridLayout4);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Stage Name';
            app.stageNameText = uieditfield(app.GridLayout4, 'text');
            app.stageNameText.Tag = 'stageNameText';
            app.stageNameText.Value = 'STAGENAME';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.text5 = uilabel(app.GridLayout5);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Dry Mass';
            app.dryMassText = uieditfield(app.GridLayout5, 'text');
            app.dryMassText.ValueChangedFcn = createCallbackFcn(app, @dryMassText_Callback, true);
            app.dryMassText.Tag = 'dryMassText';
            app.dryMassText.Value = '1';
            app.text6 = uilabel(app.GridLayout5);
            app.text6.Tag = 'text6';
            app.text6.Text = 'mT';
            app.optCheckbox = uicheckbox(app.GridLayout5);
            app.optCheckbox.ValueChangedFcn = createCallbackFcn(app, @optCheckbox_Callback, true);
            app.optCheckbox.Tag = 'optCheckbox';
            app.optCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.optCheckbox.Text = 'Opt?';
            app.optCheckbox.Value = true;
            app.lbText = uieditfield(app.GridLayout5, 'text');
            app.lbText.ValueChangedFcn = createCallbackFcn(app, @lbText_Callback, true);
            app.lbText.Tag = 'lbText';
            app.lbText.Tooltip = 'Parameter lower bound.';
            app.lbText.Value = '0.0';
            app.ubText = uieditfield(app.GridLayout5, 'text');
            app.ubText.ValueChangedFcn = createCallbackFcn(app, @ubText_Callback, true);
            app.ubText.Tag = 'ubText';
            app.ubText.Tooltip = 'Parameter upper bound.';
            app.ubText.Value = '0.0';
            % Show the figure after all components are created
            app.lvd_EditStageGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditStageGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditStageGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditStageGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditStageGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditStageGUI)
        end
    end
end