classdef lvd_EditStagesGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditStagesGUI    matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        Panel                matlab.ui.container.Panel
        GridLayout2          matlab.ui.container.GridLayout
        GridLayout4          matlab.ui.container.GridLayout
        removeStageButton    matlab.ui.control.Button
        addStageButton       matlab.ui.control.Button
        GridLayout3          matlab.ui.container.GridLayout
        GridLayout5          matlab.ui.container.GridLayout
        moveStageDownButton  matlab.ui.control.Button
        moveStageUpButton    matlab.ui.control.Button
        stagesListbox        matlab.ui.control.ListBox
        text2                matlab.ui.control.Label
        closeButton          matlab.ui.control.Button
    end


    methods (Access = private)
        function populateGUI(app, ~, ~)
            [~, ~] = app.setListboxStr();
        end
        
        function [stagesListStr, stages] = setListboxStr(app)
            lvdData = getappdata(app.lvd_EditStagesGUI,'lvdData');
            [stagesListStr, stages] = lvdData.launchVehicle.getStagesListBoxStr();
            app.stagesListbox.Items = stagesListStr;
            app.stagesListbox.ItemsData = stages;
            
            num = numel(stages);
            if(num <= 1)
                app.removeStageButton.Enable = 'off';
            else
                app.removeStageButton.Enable = 'on';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditStagesGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditStagesGUI (see VARARGIN)

            % Choose default command line output for lvd_EditStagesGUI
            handles.output = hObject;

            centerUIFigure(app.lvd_EditStagesGUI);
            applySelectedThemeToApp(app);

            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            populateGUI(app, handles, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditStagesGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditStagesGUI);
        end

        % Button pushed function: addStageButton
        function addStageButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addStageButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditStagesGUI,'lvdData');
            lv = lvdData.launchVehicle;

            stage = LaunchVehicleStage(lv);
%             useStage = lvd_EditStageGUI(stage);

            output = AppDesignerGUIOutput({false});
            lvd_EditStageGUI_App(stage, output);
            useStage = output.output{1};

            if(useStage)
                lv.addStage(stage);

                stageState = LaunchVehicleStageState(stage);
                lvdData.initStateModel.addStageState(stageState);

                [~, ~] = app.setListboxStr();

                handles.removeStageButton.Enable = 'on';
            end
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to closeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditStagesGUI);
        end

        % Window key press function: lvd_EditStagesGUI
        function lvd_EditStagesGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditStagesGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            switch(eventdata.Key)
                case 'return'
                    close(handles.lvd_EditStagesGUI);
                case 'enter'
                    close(handles.lvd_EditStagesGUI);
                case 'escape'
                    close(handles.lvd_EditStagesGUI);
            end
        end

        % Button pushed function: moveStageDownButton
        function moveStageDownButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to moveStageDownButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditStagesGUI,'lvdData');
            lv = lvdData.launchVehicle;

            stage = app.stagesListbox.Value;

            lv.moveStageDown(stage);

            [~, ~] = app.setListboxStr();
            app.stagesListbox.Value = stage;
        end

        % Button pushed function: moveStageUpButton
        function moveStageUpButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to moveStageUpButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditStagesGUI,'lvdData');
            lv = lvdData.launchVehicle;

            stage = app.stagesListbox.Value;

            lv.moveStageUp(stage);

%             if(selStage>1)
%                 set(handles.stagesListbox,'Value',selStage-1);
%             end

            [~, ~] = app.setListboxStr();
            app.stagesListbox.Value = stage;
        end

        % Button pushed function: removeStageButton
        function removeStageButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeStageButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditStagesGUI,'lvdData');
            lv = lvdData.launchVehicle;

            stage = app.stagesListbox.Value;

            tf = stage.isStageAndChildrenInUse();

            if(tf == false)
                lvdData.initStateModel.removeStageStateForStage(stage);
                lv.removeStage(stage);

%                 numStages = lv.getNumStages();
%                 if(selStage > numStages)
%                     set(handles.stagesListbox,'Value',numStages);
%                 end
                
                [~, ~] = app.setListboxStr();
            else
                msg = sprintf('Could not delete the stage "%s" because it (or an associated engine or tank) is in use as part of an event termination condition, event action, objective function, or constraint.  Remove all tanks and engines from the stage and remove the stage dependencies before attempting to delete the stage.', stage.name);
%                 warndlg(msg,'Cannot Delete Stage','modal');
                uialert(app.lvd_EditStagesGUI, msg, 'Cannot Delete Stage',"Modal",true);
            end
        end

        % Value changed function: stagesListbox
        function stagesListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to stagesListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns stagesListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from stagesListbox
%             if(strcmpi(get(handles.lvd_EditStagesGUI,'SelectionType'),'open'))
%                 lvdData = getappdata(handles.lvd_EditStagesGUI,'lvdData');
%                 lv = lvdData.launchVehicle;
% 
%                 selStage = get(handles.stagesListbox,'Value');
%                 stage = lv.getStageForInd(selStage);
% 
%                 lvd_EditStageGUI(stage);
%                 set(handles.stagesListbox,'String',lvdData.launchVehicle.getStagesListBoxStr());
%             end
        end

        % Window button down function: lvd_EditStagesGUI
        function lvd_EditStagesGUIWindowButtonDown(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            selType = app.lvd_EditStagesGUI.SelectionType;
            curObj = app.lvd_EditStagesGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.stagesListbox && numel(app.stagesListbox.ItemsData) > 0)
                    stage = app.stagesListbox.Value;
    
%                     lvd_EditStageGUI(stage);
                    output = AppDesignerGUIOutput({false});
                    lvd_EditStageGUI_App(stage, output);

                    [~, ~] = app.setListboxStr();
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditStagesGUI = uifigure('Visible', 'off');
            app.lvd_EditStagesGUI.Name = 'Edit Stages';
            app.lvd_EditStagesGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditStagesGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_EditStagesGUIWindowButtonDown, true);
            app.lvd_EditStagesGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditStagesGUI_WindowKeyPressFcn, true);
            app.lvd_EditStagesGUI.Tag = 'lvd_EditStagesGUI';
            app.GridLayout = uigridlayout(app.lvd_EditStagesGUI);
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Icon = 'close_window(1).png';
            app.closeButton.Text = 'Close';
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Stages';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.stagesListbox = uilistbox(app.GridLayout3);
            app.stagesListbox.Items = {'Listbox'};
            app.stagesListbox.ValueChangedFcn = createCallbackFcn(app, @stagesListbox_Callback, true);
            app.stagesListbox.Tag = 'stagesListbox';
            app.stagesListbox.Value = 'Listbox';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.moveStageUpButton = uibutton(app.GridLayout5, 'push');
            app.moveStageUpButton.ButtonPushedFcn = createCallbackFcn(app, @moveStageUpButton_Callback, true);
            app.moveStageUpButton.Tag = 'moveStageUpButton';
            app.moveStageUpButton.Icon = 'up-arrow.png';
            app.moveStageUpButton.Tooltip = 'Move stage up in list.';
            app.moveStageUpButton.Text = '';
            app.moveStageDownButton = uibutton(app.GridLayout5, 'push');
            app.moveStageDownButton.ButtonPushedFcn = createCallbackFcn(app, @moveStageDownButton_Callback, true);
            app.moveStageDownButton.Tag = 'moveStageDownButton';
            app.moveStageDownButton.Icon = 'down-arrow.png';
            app.moveStageDownButton.Tooltip = 'Move stage down in list.';
            app.moveStageDownButton.Text = '';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.addStageButton = uibutton(app.GridLayout4, 'push');
            app.addStageButton.ButtonPushedFcn = createCallbackFcn(app, @addStageButton_Callback, true);
            app.addStageButton.Tag = 'addStageButton';
            app.addStageButton.Icon = 'add.png';
            app.addStageButton.Text = 'Add Stage';
            app.removeStageButton = uibutton(app.GridLayout4, 'push');
            app.removeStageButton.ButtonPushedFcn = createCallbackFcn(app, @removeStageButton_Callback, true);
            app.removeStageButton.Tag = 'removeStageButton';
            app.removeStageButton.Icon = 'remove.png';
            app.removeStageButton.Text = 'Remove Stage';
            % Show the figure after all components are created
            app.lvd_EditStagesGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditStagesGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditStagesGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditStagesGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditStagesGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditStagesGUI)
        end
    end
end