classdef lvd_EditStopwatchGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditStopwatchGUI  matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        uipanel1              matlab.ui.container.Panel
        GridLayout3           matlab.ui.container.GridLayout
        GridLayout4           matlab.ui.container.GridLayout
        initialValueText      matlab.ui.control.EditField
        text6                 matlab.ui.control.Label
        startRunningCombo     matlab.ui.control.DropDown
        stopwatchNameText     matlab.ui.control.EditField
        text7                 matlab.ui.control.Label
        text5                 matlab.ui.control.Label
        stageName             matlab.ui.control.Label
        GridLayout2           matlab.ui.container.GridLayout
        cancelButton          matlab.ui.control.Button
        saveAndCloseButton    matlab.ui.control.Button
        text2                 matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, stopwatch)
            runningStateStrs = StopwatchRunningEnum.getNameStrs();
            set(handles.startRunningCombo,'String',runningStateStrs);

            ind = StopwatchRunningEnum.getIndOfListboxStrs(stopwatch.startOn);
            set(handles.startRunningCombo,'Value',ind);

            set(handles.stopwatchNameText,'String',stopwatch.name);
            set(handles.initialValueText,'String',fullAccNum2Str(stopwatch.startValue));
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            val = str2double(get(handles.initialValueText,'String'));
            enteredStr = get(handles.initialValueText,'String');
            numberName = 'Initial Value';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
        end


        function saveAndClose(app, handles)
            stopwatch = getappdata(handles.lvd_EditStopwatchGUI, 'stopwatch');

            name = handles.stopwatchNameText.String;
            initialValue = str2double(handles.initialValueText.String);

            [~, m] = StopwatchRunningEnum.getNameStrs();
            startOn = m(get(handles.startRunningCombo,'Value'));

            stopwatch.name = name;
            stopwatch.startValue = initialValue;
            stopwatch.startOn = startOn;

            app.output.output = {true};
            close(handles.lvd_EditStopwatchGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditStopwatchGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditStopwatchGUI (see VARARGIN)

            % Choose default command line output for lvd_EditStopwatchGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            stopwatch = varargin{1};
            setappdata(hObject, 'stopwatch', stopwatch);

            app.output = varargin{2};

            populateGUI(app, handles, stopwatch);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditStopwatchGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditStopwatchGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditStopwatchGUI);
        end

        % Value changed function: initialValueText
        function initialValueText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to initialValueText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of initialValueText as text
            %        str2double(get(hObject,'String')) returns contents of initialValueText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Window key press function: lvd_EditStopwatchGUI
        function lvd_EditStopwatchGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditStopwatchGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditStopwatchGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
%                 uiresume(handles.lvd_EditStopwatchGUI);
                app.saveAndClose(handles);
            else
%                 msgbox(errMsg,'Invalid Stopwatch Inputs','error');
                uialert(app.lvd_EditStopwatchGUI, errMsg, 'Invalid Stopwatch Inputs', "Icon","error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditStopwatchGUI = uifigure('Visible', 'off');
            app.lvd_EditStopwatchGUI.Name = 'Edit Stopwatch';
            app.lvd_EditStopwatchGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditStopwatchGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditStopwatchGUI_WindowKeyPressFcn, true);
            app.lvd_EditStopwatchGUI.Tag = 'lvd_EditStopwatchGUI';
            app.GridLayout = uigridlayout(app.lvd_EditStopwatchGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Stopwatch';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.stageName = uilabel(app.GridLayout3);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Stopwatch Name';
            app.text5 = uilabel(app.GridLayout3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Initial Value';
            app.text7 = uilabel(app.GridLayout3);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Start Running?';
            app.stopwatchNameText = uieditfield(app.GridLayout3, 'text');
            app.stopwatchNameText.Tag = 'stopwatchNameText';
            app.stopwatchNameText.Value = 'ENGINE NAME';
            app.startRunningCombo = uidropdown(app.GridLayout3);
            app.startRunningCombo.Items = {'False', 'True'};
            app.startRunningCombo.Tag = 'startRunningCombo';
            app.startRunningCombo.Value = 'False';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.text6 = uilabel(app.GridLayout4);
            app.text6.Tag = 'text6';
            app.text6.Text = 'sec';
            app.initialValueText = uieditfield(app.GridLayout4, 'text');
            app.initialValueText.ValueChangedFcn = createCallbackFcn(app, @initialValueText_Callback, true);
            app.initialValueText.Tag = 'initialValueText';
            app.initialValueText.Value = '0';
            % Show the figure after all components are created
            app.lvd_EditStopwatchGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditStopwatchGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditStopwatchGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditStopwatchGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditStopwatchGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditStopwatchGUI)
        end
    end
end