classdef lvd_EditStopwatchesGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditStopwatchesGUI  matlab.ui.Figure
        GridLayout3             matlab.ui.container.GridLayout
        GridLayout              matlab.ui.container.GridLayout
        uipanel1                matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        removeStopwatchButton   matlab.ui.control.Button
        addStopwatchButton      matlab.ui.control.Button
        stopwatchesListBox      matlab.ui.control.ListBox
        closeButton             matlab.ui.control.Button
        text2                   matlab.ui.control.Label
    end

    
    methods (Access = private)
        function populateGUI(app, handles, lvdData)
                [~, stopwatches] = app.setListboxItems();

                numStopwatches = numel(stopwatches);
                if(numStopwatches <= 0)
                    handles.removeStopwatchButton.Enable = 'off';
                end
        end 
        
        function [swListStr, stopwatches] = setListboxItems(app)
            lvdData = getappdata(app.lvd_EditStopwatchesGUI,'lvdData');
            [swListStr, stopwatches] = lvdData.launchVehicle.getStopwatchesListBoxStr();
            app.stopwatchesListBox.Items = swListStr;
            app.stopwatchesListBox.ItemsData = stopwatches;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditStopwatchesGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
                % This function has no output args, see OutputFcn.
                % hObject    handle to figure
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
                % varargin   command line arguments to lvd_EditStopwatchesGUI (see VARARGIN)
            
                % Choose default command line output for lvd_EditStopwatchesGUI
                handles.output = hObject;
                
                centerUIFigure(app.lvd_EditStopwatchesGUI);
            
                lvdData = varargin{1};
                setappdata(hObject,'lvdData',lvdData);
            
                populateGUI(app, handles, lvdData);
            
                % Update handles structure
                guidata(hObject, handles);
            
                % UIWAIT makes lvd_EditStopwatchesGUI wait for user response (see UIRESUME)
                uiwait(handles.lvd_EditStopwatchesGUI);
        end

        % Button pushed function: addStopwatchButton
        function addStopwatchButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to addStopwatchButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.lvd_EditStopwatchesGUI,'lvdData');
            
                stopwatch = LaunchVehicleStopwatch(lvdData);
                useStopwatch = lvd_EditStopwatchGUI(stopwatch);
            
                if(useStopwatch)
                    lvdData.launchVehicle.addStopwatch(stopwatch);
            
                    [~, ~] = app.setListboxItems();
            
                    handles.removeStopwatchButton.Enable = 'on';
                end
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to closeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                close(handles.lvd_EditStopwatchesGUI);
        end

        % Window key press function: lvd_EditStopwatchesGUI
        function lvd_EditStopwatchesGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to lvd_EditStopwatchesGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
                switch(eventdata.Key)
                    case 'return'
                        uiresume(handles.lvd_EditStopwatchesGUI);
                    case 'enter'
                        uiresume(handles.lvd_EditStopwatchesGUI);
                    case 'escape'
                        uiresume(handles.lvd_EditStopwatchesGUI);
                end
        end

        % Button pushed function: removeStopwatchButton
        function removeStopwatchButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to removeStopwatchButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.lvd_EditStopwatchesGUI,'lvdData');
                lv = lvdData.launchVehicle;
            
%                 selSw = get(handles.stopwatchesListBox,'Value');
%                 stopwatch = lv.getStopwatchForInd(selSw);
                stopwatch = app.stopwatchesListBox.Value;
            
                tf = stopwatch.isInUse();
            
                if(tf == false)
                    lv.removeStopwatch(stopwatch);
                    
                    [~, stopwatches] = app.setListboxItems();
            
                    if(numel(stopwatches) <= 0)
                        handles.removeStopwatchButton.Enable = 'off';
                    end
                else
                    warndlg(sprintf('Could not delete the stopwatch "%s" because it is in use as part of an event termination condition, event action, objective function, or constraint.  Remove these stopwatch dependencies before attempting to delete the stopwatch.', stopwatch.name),'Cannot Delete Stopwatch','modal');
                end
        end

        % Value changed function: stopwatchesListBox
        function stopwatchesListBox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to stopwatchesListBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns stopwatchesListBox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from stopwatchesListBox
%                 if(strcmpi(get(handles.lvd_EditStopwatchesGUI,'SelectionType'),'open'))
%                     lvdData = getappdata(handles.lvd_EditStopwatchesGUI,'lvdData');
%                     lv = lvdData.launchVehicle;
%             
%                     selSW = get(handles.stopwatchesListBox,'Value');
%                     stopwatch = lv.getStopwatchForInd(selSW);
%             
%                     lvd_EditStopwatchGUI(stopwatch);
%             
%                     set(handles.stopwatchesListBox,'String',lvdData.launchVehicle.getStopwatchesListBoxStr());
%                 end
        end

        % Window button down function: lvd_EditStopwatchesGUI
        function lvd_EditStopwatchesGUIWindowButtonDown(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            selType = app.lvd_EditStopwatchesGUI.SelectionType;
            curObj = app.lvd_EditStopwatchesGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.stopwatchesListBox)
                    stopwatch = app.stopwatchesListBox.Value;
                    lvd_EditStopwatchGUI(stopwatch);
            
                    [~, ~] = app.setListboxItems();
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditStopwatchesGUI = uifigure('Visible', 'off');
            app.lvd_EditStopwatchesGUI.Name = 'Edit Stopwatches';
            app.lvd_EditStopwatchesGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditStopwatchesGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_EditStopwatchesGUIWindowButtonDown, true);
            app.lvd_EditStopwatchesGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditStopwatchesGUI_WindowKeyPressFcn, true);
            app.lvd_EditStopwatchesGUI.Tag = 'lvd_EditStopwatchesGUI';
            app.GridLayout = uigridlayout(app.lvd_EditStopwatchesGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Stopwatches';
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Text = 'Close';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout2 = uigridlayout(app.uipanel1);
            app.stopwatchesListBox = uilistbox(app.GridLayout2);
            app.stopwatchesListBox.Items = {'Listbox'};
            app.stopwatchesListBox.ValueChangedFcn = createCallbackFcn(app, @stopwatchesListBox_Callback, true);
            app.stopwatchesListBox.Tag = 'stopwatchesListBox';
            app.stopwatchesListBox.Value = 'Listbox';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.addStopwatchButton = uibutton(app.GridLayout3, 'push');
            app.addStopwatchButton.ButtonPushedFcn = createCallbackFcn(app, @addStopwatchButton_Callback, true);
            app.addStopwatchButton.Tag = 'addStopwatchButton';
            app.addStopwatchButton.Text = 'Add Stopwatch';
            app.removeStopwatchButton = uibutton(app.GridLayout3, 'push');
            app.removeStopwatchButton.ButtonPushedFcn = createCallbackFcn(app, @removeStopwatchButton_Callback, true);
            app.removeStopwatchButton.Tag = 'removeStopwatchButton';
            app.removeStopwatchButton.Text = 'Remove Stopwatch';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout2 = uigridlayout(app.uipanel1);
            app.stopwatchesListBox = uilistbox(app.GridLayout2);
            app.stopwatchesListBox.Items = {'Listbox'};
            app.stopwatchesListBox.ValueChangedFcn = createCallbackFcn(app, @stopwatchesListBox_Callback, true);
            app.stopwatchesListBox.Tag = 'stopwatchesListBox';
            app.stopwatchesListBox.Value = 'Listbox';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.addStopwatchButton = uibutton(app.GridLayout3, 'push');
            app.addStopwatchButton.ButtonPushedFcn = createCallbackFcn(app, @addStopwatchButton_Callback, true);
            app.addStopwatchButton.Tag = 'addStopwatchButton';
            app.addStopwatchButton.Text = 'Add Stopwatch';
            app.removeStopwatchButton = uibutton(app.GridLayout3, 'push');
            app.removeStopwatchButton.ButtonPushedFcn = createCallbackFcn(app, @removeStopwatchButton_Callback, true);
            app.removeStopwatchButton.Tag = 'removeStopwatchButton';
            app.removeStopwatchButton.Text = 'Remove Stopwatch';
            % Show the figure after all components are created
            app.lvd_EditStopwatchesGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditStopwatchesGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditStopwatchesGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditStopwatchesGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditStopwatchesGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditStopwatchesGUI)
        end
    end
end