classdef lvd_EditTankFluidTypesGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditTankFluidTypesGUI  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        Panel                      matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        GridLayout5                matlab.ui.container.GridLayout
        GridLayout6                matlab.ui.container.GridLayout
        moveTypeDownButton         matlab.ui.control.Button
        moveTypeUpButton           matlab.ui.control.Button
        typesListbox               matlab.ui.control.ListBox
        GridLayout4                matlab.ui.container.GridLayout
        removeRemoveTypeButton     matlab.ui.control.Button
        addTypeButton              matlab.ui.control.Button
        uipanel2                   matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        text6                      matlab.ui.control.Label
        text5                      matlab.ui.control.Label
        closeButton                matlab.ui.control.Button
        text2                      matlab.ui.control.Label
    end


    methods (Access = private)
        function tf = askAndSetNewNameForType(~, type)
            answer = inputdlg('Enter a new name for the fluid:','Edit Fluid Type',1,{type.name});
            
            if(not(isempty(answer)))
                answer = strtrim(answer{1});
                if(not(isempty(answer)))
                    type.name = answer;
                    tf = true;
                else
                    errordlg('The input name for the fluid type must contain more than whitespace and must not be zero length.');
                    tf = false;
                end
            else
                tf = false;
            end
        end

        function populateGUI(app, handles, ~)
            [~, tankTypes] = app.setListboxStr();

            numTypes = numel(tankTypes);
            if(numTypes <= 3)
                handles.removeRemoveTypeButton.Enable = 'off';
            end
        end

        function [listboxStr, tankTypes] = setListboxStr(app)
            lvdData = getappdata(app.lvd_EditTankFluidTypesGUI,'lvdData');
            [listboxStr, tankTypes] = lvdData.launchVehicle.tankTypes.getListboxStr();
            app.typesListbox.Items = listboxStr;
            app.typesListbox.ItemsData = tankTypes;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditTankFluidTypesGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditTankFluidTypesGUI (see VARARGIN)

            % Choose default command line output for lvd_EditTankFluidTypesGUI
            handles.output = hObject;

            centerUIFigure(app.lvd_EditTankFluidTypesGUI);
            applySelectedThemeToApp(app);

            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            populateGUI(app, handles, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditTankFluidTypesGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditTankFluidTypesGUI);
        end

        % Button pushed function: addTypeButton
        function addTypeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addTypeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditTankFluidTypesGUI,'lvdData');
            lv = lvdData.launchVehicle;

            type = TankFluidType('New Fluid');
            tf = askAndSetNewNameForType(app, type);

            if(tf)
                lv.tankTypes.addType(type);
    
                [~, ~] = app.setListboxStr();
                app.typesListbox.Value = type;
    
                handles.removeRemoveTypeButton.Enable = 'on';
            end
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to closeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditTankFluidTypesGUI);
        end

        % Window key press function: lvd_EditTankFluidTypesGUI
        function lvd_EditTankFluidTypesGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditTankFluidTypesGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            switch(eventdata.Key)
                case 'return'
                    close(handles.lvd_EditTankFluidTypesGUI);
                case 'enter'
                    close(handles.lvd_EditTankFluidTypesGUI);
                case 'escape'
                    close(handles.lvd_EditTankFluidTypesGUI);
            end
        end

        % Button pushed function: moveTypeDownButton
        function moveTypeDownButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to moveTypeDownButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditTankFluidTypesGUI,'lvdData');
            lv = lvdData.launchVehicle;

            type = app.typesListbox.Value;

            lv.tankTypes.moveTypeDown(type);

            [~, ~] = app.setListboxStr();
            app.typesListbox.Value = type;
        end

        % Button pushed function: moveTypeUpButton
        function moveTypeUpButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to moveTypeUpButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditTankFluidTypesGUI,'lvdData');
            lv = lvdData.launchVehicle;

            type = app.typesListbox.Value;

            lv.tankTypes.moveTypeUp(type);

            [~, ~] = app.setListboxStr();
            app.typesListbox.Value = type;
        end

        % Button pushed function: removeRemoveTypeButton
        function removeRemoveTypeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeRemoveTypeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditTankFluidTypesGUI,'lvdData');
            lv = lvdData.launchVehicle;

            %             selType = get(handles.typesListbox,'Value');
            %             type = lv.tankTypes.getTypeForInd(selType);
            type = app.typesListbox.Value;

            tf = type.isInUse(lvdData);

            if(tf == false)
                lv.tankTypes.removeType(type);

                [~, tankTypes] = app.setListboxStr();
                numTypes = length(tankTypes);

                if(numTypes <= 3)
                    handles.removeRemoveTypeButton.Enable = 'off';
                end
            else
                msg = sprintf('Could not delete the fluid type "%s" because it is in use on an existing tank.  Set any tanks that use this fluid type to a different fluid type before deleting.', type.name);
                %                 warndlg(msg,'Cannot Delete Fluid Type','modal');
                uialert(app.lvd_EditTankFluidTypesGUI, msg, 'Cannot Delete Fluid Type',"Modal",true);
            end
        end

        % Value changed function: typesListbox
        function typesListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to typesListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns typesListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from typesListbox
            %             if(strcmpi(get(handles.lvd_EditTankFluidTypesGUI,'SelectionType'),'open'))
            %                 lvdData = getappdata(handles.lvd_EditTankFluidTypesGUI,'lvdData');
            %                 lv = lvdData.launchVehicle;
            %
            %                 selType = get(handles.typesListbox,'Value');
            %                 type = lv.tankTypes.getTypeForInd(selType);
            %                 askAndSetNewNameForType(app, type);
            %
            %                 set(handles.typesListbox,'String',lvdData.launchVehicle.tankTypes.getListboxStr());
            %             end
        end

        % Window button down function: lvd_EditTankFluidTypesGUI
        function lvd_EditTankFluidTypesGUIWindowButtonDown(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            selType = app.lvd_EditTankFluidTypesGUI.SelectionType;
            curObj = app.lvd_EditTankFluidTypesGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.typesListbox && numel(app.typesListbox.ItemsData) > 0)
                    lvdData = getappdata(handles.lvd_EditTankFluidTypesGUI,'lvdData');
                    lv = lvdData.launchVehicle;

                    selType = get(handles.typesListbox,'Value');
                    type = lv.tankTypes.getTypeForInd(selType);
                    askAndSetNewNameForType(app, type);

                    [~, ~] = app.setListboxStr();
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditTankFluidTypesGUI = uifigure('Visible', 'off');
            app.lvd_EditTankFluidTypesGUI.Name = 'Edit Tank Fluid Types';
            app.lvd_EditTankFluidTypesGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditTankFluidTypesGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_EditTankFluidTypesGUIWindowButtonDown, true);
            app.lvd_EditTankFluidTypesGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditTankFluidTypesGUI_WindowKeyPressFcn, true);
            app.lvd_EditTankFluidTypesGUI.Tag = 'lvd_EditTankFluidTypesGUI';
            app.GridLayout = uigridlayout(app.lvd_EditTankFluidTypesGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Tank Fluid Types';
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Icon = 'close_window(1).png';
            app.closeButton.Text = 'Close';
            app.uipanel2 = uipanel(app.GridLayout);
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout2 = uigridlayout(app.uipanel2);
            app.text5 = uilabel(app.GridLayout2);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Notice:';
            app.text6 = uilabel(app.GridLayout2);
            app.text6.Tag = 'text6';
            app.text6.Tooltip = 'Any mass associated with fluid types beyond the first 3 in the list will be lumped in with Dry Mass in the state displays.';
            app.text6.Text = 'Any mass associated with fluid types beyond the first 3 in the list will be lumped in with Dry Mass in the state displays.';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.addTypeButton = uibutton(app.GridLayout4, 'push');
            app.addTypeButton.ButtonPushedFcn = createCallbackFcn(app, @addTypeButton_Callback, true);
            app.addTypeButton.Tag = 'addTypeButton';
            app.addTypeButton.Icon = 'add.png';
            app.addTypeButton.Text = 'Add Fluid Type';
            app.removeRemoveTypeButton = uibutton(app.GridLayout4, 'push');
            app.removeRemoveTypeButton.ButtonPushedFcn = createCallbackFcn(app, @removeRemoveTypeButton_Callback, true);
            app.removeRemoveTypeButton.Tag = 'removeRemoveTypeButton';
            app.removeRemoveTypeButton.Icon = 'remove.png';
            app.removeRemoveTypeButton.Tooltip = 'For backwards compatibility with Mission Architect, at least three fluid types must be available. ';
            app.removeRemoveTypeButton.Text = 'Remove Fluid Type';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.typesListbox = uilistbox(app.GridLayout5);
            app.typesListbox.Items = {'Listbox'};
            app.typesListbox.ValueChangedFcn = createCallbackFcn(app, @typesListbox_Callback, true);
            app.typesListbox.Tag = 'typesListbox';
            app.typesListbox.Tooltip = 'A list of the tank fluid types that are currently available on this launch vehicle.  The first three will be shown in the state displays.';
            app.typesListbox.Value = 'Listbox';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.moveTypeUpButton = uibutton(app.GridLayout6, 'push');
            app.moveTypeUpButton.ButtonPushedFcn = createCallbackFcn(app, @moveTypeUpButton_Callback, true);
            app.moveTypeUpButton.Tag = 'moveTypeUpButton';
            app.moveTypeUpButton.Icon = 'up-arrow.png';
            app.moveTypeUpButton.Tooltip = 'Move fluid type up in list.';
            app.moveTypeUpButton.Text = '';
            app.moveTypeDownButton = uibutton(app.GridLayout6, 'push');
            app.moveTypeDownButton.ButtonPushedFcn = createCallbackFcn(app, @moveTypeDownButton_Callback, true);
            app.moveTypeDownButton.Tag = 'moveTypeDownButton';
            app.moveTypeDownButton.Icon = 'down-arrow.png';
            app.moveTypeDownButton.Tooltip = 'Move fluid type down in list.';
            app.moveTypeDownButton.Text = '';
            % Show the figure after all components are created
            app.lvd_EditTankFluidTypesGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditTankFluidTypesGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditTankFluidTypesGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditTankFluidTypesGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditTankFluidTypesGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditTankFluidTypesGUI)
        end
    end
end