classdef lvd_EditTankGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditTankGUI     matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        uipanel1            matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        GridLayout5         matlab.ui.container.GridLayout
        text8               matlab.ui.control.Label
        text7               matlab.ui.control.Label
        capacityText        matlab.ui.control.EditField
        text15              matlab.ui.control.Label
        GridLayout4         matlab.ui.container.GridLayout
        ubText              matlab.ui.control.EditField
        lbText              matlab.ui.control.EditField
        optCheckbox         matlab.ui.control.CheckBox
        text6               matlab.ui.control.Label
        initPropMassText    matlab.ui.control.EditField
        fluidTypeCombo      matlab.ui.control.DropDown
        stageCombo          matlab.ui.control.DropDown
        tankNameText        matlab.ui.control.EditField
        text5               matlab.ui.control.Label
        text14              matlab.ui.control.Label
        text13              matlab.ui.control.Label
        stageName           matlab.ui.control.Label
        GridLayout2         matlab.ui.container.GridLayout
        cancelButton        matlab.ui.control.Button
        saveAndCloseButton  matlab.ui.control.Button
        text2               matlab.ui.control.Label
    end

    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, tank)
            lv = tank.stage.launchVehicle;
            [stagesListboxStr, ~] = lv.getStagesListBoxStr();
            ind = lv.getIndOfStage(tank.stage);

            if(isempty(ind))
                ind = 1;
            end

            set(handles.tankNameText,'String',tank.name);
            set(handles.stageCombo,'String',stagesListboxStr);
            set(handles.stageCombo,'Value',ind);
            set(handles.initPropMassText,'String',fullAccNum2Str(tank.getInitialMass()));

            app.capacityText.Value = fullAccNum2Str(tank.getCapacity());

            typeListboxStr = lv.tankTypes.getListboxStr();
            handles.fluidTypeCombo.String = typeListboxStr;
            handles.fluidTypeCombo.Value = lv.tankTypes.getIndForType(tank.tankType);

            optVar = tank.getExistingOptVar();
            if(isempty(optVar))
                optVar = tank.getNewOptVar();
            end

            handles.optCheckbox.Value = double(optVar.getUseTfForVariable());

            [lb, ub] = optVar.getAllBndsForVariable();
            handles.lbText.String = fullAccNum2Str(lb);
            handles.ubText.String = fullAccNum2Str(ub);

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            optCheckbox_Callback(app, evt);
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            val = str2double(get(handles.initPropMassText,'String'));
            enteredStr = get(handles.initPropMassText,'String');
            numberName = 'Initial Propellant Mass';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);

            capStr = strtrim(app.capacityText.Value);
            capVal = str2double(capStr);
            numberName = 'Tank Capacity';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(capVal, numberName, lb, ub, isInt, errMsg, capStr);

            if(isempty(errMsg) && capVal < val)
                errMsg{end+1} = sprintf('Tank Capacity (%s mT) must be at least the Initial Propellant Mass (%s mT).', fullAccNum2Str(capVal), fullAccNum2Str(val));
            end

            lwrBnd = str2double(get(handles.lbText,'String'));
            enteredStr = get(handles.lbText,'String');
            numberName = 'Initial Propellant Mass (Lower Bound)';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(lwrBnd, numberName, lb, ub, isInt, errMsg, enteredStr);

            uprBnd = str2double(get(handles.ubText,'String'));
            enteredStr = get(handles.ubText,'String');
            numberName = 'Initial Propellant Mass (Upper Bound)';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(uprBnd, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                uprBnd = str2double(get(handles.ubText,'String'));
                enteredStr = get(handles.ubText,'String');
                numberName = 'Initial Propellant Mass (Upper Bound)';
                lb = lwrBnd;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(uprBnd, numberName, lb, ub, isInt, errMsg, enteredStr);

                if(isempty(errMsg) && logical(handles.optCheckbox.Value) && uprBnd > capVal)
                    errMsg{end+1} = sprintf('Initial Propellant Mass (Upper Bound) (%s mT) must not exceed the Tank Capacity (%s mT).', fullAccNum2Str(uprBnd), fullAccNum2Str(capVal));
                end
            end
        end

        
        function saveAndClose(app, handles)
            tank = getappdata(handles.lvd_EditTankGUI, 'tank');
            lv = tank.stage.launchVehicle;
            
            conns = lv.getEngineToTankConnsForTank(tank);
            
            stage = lv.getStageForInd(handles.stageCombo.Value);
            
            name = handles.tankNameText.String;
            initialMass = str2double(handles.initPropMassText.String);
            
            tank.name = name;
            tank.stage = stage;
            tank.initialMass = initialMass;

            capStr = strtrim(app.capacityText.Value);
            tank.capacity = str2double(capStr);
            
            lv.moveTankToStage(tank, stage);
            
            for(i=1:length(conns)) %#ok<NO4LP>
                conns(i).tank = tank;
                lv.addEngineToTankConnection(conns(i));
            end
            
            tank.tankType = lv.tankTypes.getTypeForInd(handles.fluidTypeCombo.Value);
            
            optVar = tank.getExistingOptVar();
            if(not(isempty(optVar)))
                tank.stage.launchVehicle.lvdData.optimizer.vars.removeVariable(optVar);
            end
            optVar = tank.getNewOptVar();
            
            optInitMass = logical(handles.optCheckbox.Value);
            lbInitMass = str2double(handles.lbText.String);
            ubInitMass = str2double(handles.ubText.String);
            
            optVar.setUseTfForVariable(optInitMass);
            optVar.setBndsForVariable(lbInitMass,ubInitMass);
            tank.stage.launchVehicle.lvdData.optimizer.vars.addVariable(optVar);
            
            app.output.output = {true};
            close(handles.lvd_EditTankGUI);
        end

        function capacityText_ValueChanged(~, src, ~)
            newInput = strtrim(src.Value);
            if(not(isempty(newInput)))
                newInput = attemptStrEval(newInput);
            end
            src.Value = newInput;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditTankGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditTankGUI (see VARARGIN)

            % Choose default command line output for lvd_EditTankGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            tank = varargin{1};
            setappdata(hObject, 'tank', tank);
            
            app.output = varargin{2};

            populateGUI(app, handles, tank);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditTankGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditTankGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditTankGUI);
        end

        % Value changed function: initPropMassText
        function initPropMassText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to initPropMassText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of initPropMassText as text
            %        str2double(get(hObject,'String')) returns contents of initPropMassText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            %A capacity that matched the old initial mass (or was never set)
            %follows the new one, so the usual "capacity = initial load" case
            %needs no second entry.
            oldCap = str2double(strtrim(app.capacityText.Value));
            oldMass = str2double(event.PreviousValue);
            if(isnan(oldCap) || oldCap == 0 || (not(isnan(oldMass)) && oldCap == oldMass))
                app.capacityText.Value = newInput;
            end
        end

        % Value changed function: lbText
        function lbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of lbText as text
            %        str2double(get(hObject,'String')) returns contents of lbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Window key press function: lvd_EditTankGUI
        function lvd_EditTankGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditTankGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditTankGUI);
            end
        end

        % Value changed function: optCheckbox
        function optCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.optCheckbox;

            % hObject    handle to optCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of optCheckbox
            if(get(hObject,'Value'))
                handles.lbText.Enable = 'on';
                handles.ubText.Enable = 'on';
            else
                handles.lbText.Enable = 'off';
                handles.ubText.Enable = 'off';
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
%                 uiresume(handles.lvd_EditTankGUI);
                app.saveAndClose(handles);
            else
%                 msgbox(errMsg,'Invalid Tank Inputs','error');
                uialert(app.lvd_EditTankGUI, errMsg, 'Invalid Tank Inputs', "Icon","error");
            end
        end

        % Value changed function: ubText
        function ubText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to ubText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of ubText as text
            %        str2double(get(hObject,'String')) returns contents of ubText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: capacityText
        function capacityTextValueChanged(app, event)
            app.capacityText_ValueChanged(app.capacityText, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditTankGUI = uifigure('Visible', 'off');
            app.lvd_EditTankGUI.Name = 'Edit Tank';
            app.lvd_EditTankGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditTankGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditTankGUI_WindowKeyPressFcn, true);
            app.lvd_EditTankGUI.Tag = 'lvd_EditTankGUI';
            app.GridLayout = uigridlayout(app.lvd_EditTankGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Tank';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.stageName = uilabel(app.GridLayout3);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Tank Name';
            app.text13 = uilabel(app.GridLayout3);
            app.text13.Tag = 'text13';
            app.text13.Tooltip = 'The stage this tank is attached to.';
            app.text13.Text = 'Stage';
            app.text14 = uilabel(app.GridLayout3);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Fluid Type';
            app.text5 = uilabel(app.GridLayout3);
            app.text5.Tag = 'text5';
            app.text5.Tooltip = 'Initial propellant load in the tank.';
            app.text5.Text = 'Prop. Mass';
            app.tankNameText = uieditfield(app.GridLayout3, 'text');
            app.tankNameText.Tag = 'tankNameText';
            app.tankNameText.Value = 'ENGINE NAME';
            app.stageCombo = uidropdown(app.GridLayout3);
            app.stageCombo.Items = {'Pop-up Menu'};
            app.stageCombo.Tag = 'stageCombo';
            app.stageCombo.Tooltip = 'The stage this tank is attached to.';
            app.stageCombo.Value = 'Pop-up Menu';
            app.fluidTypeCombo = uidropdown(app.GridLayout3);
            app.fluidTypeCombo.Items = {'Pop-up Menu'};
            app.fluidTypeCombo.Tag = 'fluidTypeCombo';
            app.fluidTypeCombo.Tooltip = 'The type of fluid in the tank.';
            app.fluidTypeCombo.Value = 'Pop-up Menu';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.initPropMassText = uieditfield(app.GridLayout4, 'text');
            app.initPropMassText.ValueChangedFcn = createCallbackFcn(app, @initPropMassText_Callback, true);
            app.initPropMassText.Tag = 'initPropMassText';
            app.initPropMassText.Tooltip = 'Initial propellant load in the tank.';
            app.initPropMassText.Value = '1';
            app.text6 = uilabel(app.GridLayout4);
            app.text6.Tag = 'text6';
            app.text6.Text = 'mT';
            app.optCheckbox = uicheckbox(app.GridLayout4);
            app.optCheckbox.ValueChangedFcn = createCallbackFcn(app, @optCheckbox_Callback, true);
            app.optCheckbox.Tag = 'optCheckbox';
            app.optCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.optCheckbox.Text = 'Opt?';
            app.optCheckbox.Value = true;
            app.lbText = uieditfield(app.GridLayout4, 'text');
            app.lbText.ValueChangedFcn = createCallbackFcn(app, @lbText_Callback, true);
            app.lbText.Tag = 'lbText';
            app.lbText.Tooltip = 'Parameter lower bound.';
            app.lbText.Value = '0.0';
            app.ubText = uieditfield(app.GridLayout4, 'text');
            app.ubText.ValueChangedFcn = createCallbackFcn(app, @ubText_Callback, true);
            app.ubText.Tag = 'ubText';
            app.ubText.Tooltip = 'Parameter upper bound.';
            app.ubText.Value = '0.0';
            app.text15 = uilabel(app.GridLayout3);
            app.text15.Tooltip = {'Maximum propellant mass the tank can hold.'};
            app.text15.Text = 'Capacity';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.capacityText = uieditfield(app.GridLayout5, 'text');
            app.capacityText.ValueChangedFcn = createCallbackFcn(app, @capacityTextValueChanged, true);
            app.capacityText.Tooltip = {'Maximum propellant mass the tank can hold, at least the initial propellant mass.  The fuel-remaining throttle curve is measured against it and crossfeed into the tank stops when it is full.'};
            app.capacityText.Value = '1';
            app.text7 = uilabel(app.GridLayout5);
            app.text7.Text = 'mT';
            app.text8 = uilabel(app.GridLayout5);
            app.text8.Text = '(at least the initial mass)';
            % Show the figure after all components are created
            app.lvd_EditTankGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditTankGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditTankGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditTankGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditTankGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditTankGUI)
        end
    end
end