classdef lvd_EditTankToTankConnGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditTankToTankConnGUI  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        uipanel1                   matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        GridLayout4                matlab.ui.container.GridLayout
        flowRateText               matlab.ui.control.EditField
        text15                     matlab.ui.control.Label
        tgtTankCombo               matlab.ui.control.DropDown
        text14                     matlab.ui.control.Label
        text13                     matlab.ui.control.Label
        srcTankCombo               matlab.ui.control.DropDown
        srcTankLabel               matlab.ui.control.Label
        GridLayout2                matlab.ui.container.GridLayout
        cancelButton               matlab.ui.control.Button
        saveAndCloseButton         matlab.ui.control.Button
        text2                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, t2TConn)
            lv = t2TConn.lvdData.launchVehicle;

            [tanksListStr, ~] = lv.getTanksListBoxStr();
            tanksListStr = horzcat('<External>',tanksListStr);

            if(not(isempty(t2TConn.srcTank)))
                indSrcTank = 1 + lv.getListBoxIndForTank(t2TConn.srcTank);
            else
                indSrcTank = 1;
            end

            if(not(isempty(t2TConn.tgtTank)))
                indTgtTank = 1 + lv.getListBoxIndForTank(t2TConn.tgtTank);
            else
                indTgtTank = 1;
            end

            set(handles.srcTankCombo,'String',tanksListStr);
            set(handles.srcTankCombo,'Value',indSrcTank);
            set(handles.tgtTankCombo,'String',tanksListStr);
            set(handles.tgtTankCombo,'Value',indTgtTank);
            set(handles.flowRateText,'String',fullAccNum2Str(t2TConn.initFlowRate));
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            val = str2double(get(handles.flowRateText,'String'));
            enteredStr = get(handles.flowRateText,'String');
            numberName = 'Initial Flow Rate';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);

            srcTankInd = handles.srcTankCombo.Value;
            tgtTankInd = handles.tgtTankCombo.Value;

            if(srcTankInd == 1 && tgtTankInd == 1)
                errMsg{end+1} = 'At least the source or destination tank must be a tank on the vehicle.  It is not allowable to have both the source and destination tank be <External>.';
            elseif(srcTankInd == tgtTankInd)
                errMsg{end+1} = 'The source and destination tanks may not be the same.  They must be different.';
            end
        end

        
        function saveAndClose(app, handles)
            t2TConn = getappdata(handles.lvd_EditTankToTankConnGUI, 't2TConn');
            lv = t2TConn.lvdData.launchVehicle;
            
            srcTankInd = handles.srcTankCombo.Value;
            if(srcTankInd == 1)
                srcTank = LaunchVehicleTank.empty(1,0);
            else
                srcTank = lv.getTankForInd(srcTankInd-1);
            end
            
            tgtTankInd = handles.tgtTankCombo.Value;
            if(tgtTankInd == 1)
                tgtTank = LaunchVehicleTank.empty(1,0);
            else
                tgtTank = lv.getTankForInd(tgtTankInd-1);
            end
            
            t2TConn.srcTank = srcTank;
            t2TConn.tgtTank = tgtTank;
            t2TConn.initFlowRate = str2double(handles.flowRateText.String);
            
            app.output.output = {true};
            close(handles.lvd_EditTankToTankConnGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditTankToTankConnGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditTankToTankConnGUI (see VARARGIN)

            % Choose default command line output for lvd_EditTankToTankConnGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            t2TConn = varargin{1};
            setappdata(hObject, 't2TConn', t2TConn);
            
            app.output = varargin{2};

            populateGUI(app, handles, t2TConn);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditTankToTankConnGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditTankToTankConnGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditTankToTankConnGUI);
        end

        % Value changed function: flowRateText
        function flowRateText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to flowRateText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of flowRateText as text
            %        str2double(get(hObject,'String')) returns contents of flowRateText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Window key press function: lvd_EditTankToTankConnGUI
        function lvd_EditTankToTankConnGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditTankToTankConnGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditTankToTankConnGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
%                 uiresume(handles.lvd_EditTankToTankConnGUI);
                app.saveAndClose(handles);
            else
%                 msgbox(errMsg,'Invalid Inputs','error');
                uialert(app.lvd_EditTankToTankConnGUI, errMsg, 'Invalid Inputs', "Icon","error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditTankToTankConnGUI = uifigure('Visible', 'off');
            app.lvd_EditTankToTankConnGUI.Name = 'Edit Tank To Tank Connection';
            app.lvd_EditTankToTankConnGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditTankToTankConnGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditTankToTankConnGUI_WindowKeyPressFcn, true);
            app.lvd_EditTankToTankConnGUI.Tag = 'lvd_EditTankToTankConnGUI';
            app.GridLayout = uigridlayout(app.lvd_EditTankToTankConnGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Tank To Tank Connection';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.srcTankLabel = uilabel(app.GridLayout3);
            app.srcTankLabel.Tag = 'srcTankLabel';
            app.srcTankLabel.Text = 'Source Tank';
            app.srcTankCombo = uidropdown(app.GridLayout3);
            app.srcTankCombo.Items = {'ENGINE NAME'};
            app.srcTankCombo.Tag = 'srcTankCombo';
            app.srcTankCombo.Tooltip = 'The tank that mass will be flowed from to the destination.  <External> here implies bringing in mass from the outside to the destination tank.';
            app.srcTankCombo.Value = 'ENGINE NAME';
            app.text13 = uilabel(app.GridLayout3);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Destination Tank';
            app.text14 = uilabel(app.GridLayout3);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Initial Flow Rate';
            app.tgtTankCombo = uidropdown(app.GridLayout3);
            app.tgtTankCombo.Items = {'Pop-up Menu'};
            app.tgtTankCombo.Tag = 'tgtTankCombo';
            app.tgtTankCombo.Tooltip = 'The tank that will recieve mass from the source tank.  External here implies that mass is being dumped overboard from the source tank.';
            app.tgtTankCombo.Value = 'Pop-up Menu';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.text15 = uilabel(app.GridLayout4);
            app.text15.Tag = 'text15';
            app.text15.Text = 'mT/s';
            app.flowRateText = uieditfield(app.GridLayout4, 'text');
            app.flowRateText.ValueChangedFcn = createCallbackFcn(app, @flowRateText_Callback, true);
            app.flowRateText.Tag = 'flowRateText';
            app.flowRateText.Tooltip = 'Initial rate of propellent flow from source tank to destination tank.  Must be positive.  Change with an event action.';
            app.flowRateText.Value = '1';
            % Show the figure after all components are created
            app.lvd_EditTankToTankConnGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditTankToTankConnGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditTankToTankConnGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditTankToTankConnGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditTankToTankConnGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditTankToTankConnGUI)
        end
    end
end