classdef lvd_EditTankToTankConnsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditTankToTanksConnsGUI  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        uipanel1                     matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        GridLayout3                  matlab.ui.container.GridLayout
        removeConnButton             matlab.ui.control.Button
        addConnButton                matlab.ui.control.Button
        connsListBox                 matlab.ui.control.ListBox
        closeButton                  matlab.ui.control.Button
        text2                        matlab.ui.control.Label
    end


    methods (Access = private)
        function populateGUI(app, ~, ~)
            [~, ~] = app.setListboxItems();
        end

        function [t2TConnStr, t2TConns] = setListboxItems(app)
            lvdData = getappdata(app.lvd_EditTankToTanksConnsGUI,'lvdData');

            [t2TConnStr, t2TConns] = lvdData.launchVehicle.getTankToTankConnectionsListBoxStr();
            app.connsListBox.Items = t2TConnStr;
            app.connsListBox.ItemsData = t2TConns;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditTankToTankConnsGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditTankToTankConnsGUI (see VARARGIN)

            % Choose default command line output for lvd_EditTankToTankConnsGUI
            handles.output = hObject;

            centerUIFigure(app.lvd_EditTankToTanksConnsGUI);
            applySelectedThemeToApp(app);

            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            populateGUI(app, handles, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditTankToTankConnsGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditTankToTanksConnsGUI);
        end

        % Button pushed function: addConnButton
        function addConnButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addConnButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditTankToTanksConnsGUI,'lvdData');

            tempSrcTank = lvdData.launchVehicle.getTankForInd(1);
            tempTgtTank = LaunchVehicleTank.empty(1,0);

            t2TConn = TankToTankConnection(tempSrcTank, tempTgtTank);
            
            output = AppDesignerGUIOutput({false});
            lvd_EditTankToTankConnGUI_App(t2TConn, output);
            useT2TConn = output.output{1};

            if(useT2TConn)
                lvdData.launchVehicle.addTankToTankConnection(t2TConn);

                lvState = lvdData.initStateModel.lvState;
                connState = TankToTankConnState(t2TConn);
                connState.flowRate = t2TConn.initFlowRate;
                lvState.addT2TConnState(connState);

                %                 set(handles.connsListBox,'String',lvdData.launchVehicle.getTankToTankConnectionsListBoxStr());
                app.setListboxItems();

                handles.removeConnButton.Enable = 'on';
            end
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to closeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditTankToTanksConnsGUI);
        end

        % Value changed function: connsListBox
        function connsListBox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to connsListBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns connsListBox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from connsListBox
            %             if(strcmpi(get(handles.lvd_EditTankToTanksConnsGUI,'SelectionType'),'open'))
            %                 lvdData = getappdata(handles.lvd_EditTankToTanksConnsGUI,'lvdData');
            %                 lv = lvdData.launchVehicle;
            %
            %                 [~,t2TConns] = lvdData.launchVehicle.getTankToTankConnectionsListBoxStr();
            %
            %                 if(~isempty(t2TConns))
            %                     selT2TConn = get(handles.connsListBox,'Value');
            %                     t2TConn = lv.getTankToTankForInd(selT2TConn);
            %
            %                     lvd_EditTankToTankConnGUI(t2TConn);
            %
            %                     lvState = lvdData.initStateModel.lvState;
            %                     connState = lvState.getTank2TankConnStateForConn(t2TConn);
            %                     connState.flowRate = t2TConn.initFlowRate;
            %
            %                     set(handles.connsListBox,'String',lvdData.launchVehicle.getTankToTankConnectionsListBoxStr());
            %                 end
            %             end
        end

        % Window key press function: lvd_EditTankToTanksConnsGUI
        function lvd_EditTankToTanksConnsGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditTankToTankConnsGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            switch(eventdata.Key)
                case 'return'
                    uiresume(handles.lvd_EditTankToTanksConnsGUI);
                case 'enter'
                    uiresume(handles.lvd_EditTankToTanksConnsGUI);
                case 'escape'
                    uiresume(handles.lvd_EditTankToTanksConnsGUI);
            end
        end

        % Button pushed function: removeConnButton
        function removeConnButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeConnButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditTankToTanksConnsGUI,'lvdData');
            lv = lvdData.launchVehicle;

            %             selConn = get(handles.connsListBox,'Value');
            %             conn = lv.getTankToTankForInd(selConn);
            conn = app.connsListBox.Value;

            tf = conn.isInUse();

            if(tf == false)
                lvState = lvdData.initStateModel.lvState;
                lvState.removeT2TConnStateForConn(conn);

                lv.removeTankToTankConnection(conn);

                %                 [listBoxStr, t2TConns] = lvdData.launchVehicle.getTankToTankConnectionsListBoxStr();

                %                 numConns = length(listBoxStr);
                %                 if(numConns == 0)
                %                     handles.connsListBox.String = ' ';
                %                     set(handles.connsListBox,'Value',1);
                %                 elseif(selConn > numConns)
                %                     set(handles.connsListBox,'Value',numConns);
                %                 end

                %                 set(handles.connsListBox,'String',listBoxStr);

                [~, ~] = app.setListboxItems();
            else
                warndlg(sprintf('Could not delete the "%s" connection because it is in use as part of an event termination condition, event action, objective function, or constraint.  Remove the dependencies before attempting to delete the connection.', conn.getName()),'Cannot Delete Connection','modal');
            end
        end

        % Window button down function: lvd_EditTankToTanksConnsGUI
        function lvd_EditTankToTanksConnsGUIWindowButtonDown(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            selType = app.lvd_EditTankToTanksConnsGUI.SelectionType;
            curObj = app.lvd_EditTankToTanksConnsGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.connsListBox && numel(app.connsListBox.ItemsData) > 0)
                    lvdData = getappdata(handles.lvd_EditTankToTanksConnsGUI,'lvdData');

                    [~,t2TConns] = lvdData.launchVehicle.getTankToTankConnectionsListBoxStr();

                    if(~isempty(t2TConns))
                        t2TConn = app.connsListBox.Value;

                        output = AppDesignerGUIOutput({false});
                        lvd_EditTankToTankConnGUI_App(t2TConn, output);

                        lvState = lvdData.initStateModel.lvState;
                        connState = lvState.getTank2TankConnStateForConn(t2TConn);
                        connState.flowRate = t2TConn.initFlowRate;

                        app.setListboxItems();
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditTankToTanksConnsGUI = uifigure('Visible', 'off');
            app.lvd_EditTankToTanksConnsGUI.Name = 'Edit Tank To Tank Connections';
            app.lvd_EditTankToTanksConnsGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditTankToTanksConnsGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_EditTankToTanksConnsGUIWindowButtonDown, true);
            app.lvd_EditTankToTanksConnsGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditTankToTanksConnsGUI_WindowKeyPressFcn, true);
            app.lvd_EditTankToTanksConnsGUI.Tag = 'lvd_EditTankToTanksConnsGUI';
            app.GridLayout = uigridlayout(app.lvd_EditTankToTanksConnsGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Tank to Tank Connections';
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Icon = 'close_window(1).png';
            app.closeButton.Text = 'Close';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout2 = uigridlayout(app.uipanel1);
            app.connsListBox = uilistbox(app.GridLayout2);
            app.connsListBox.Items = {'Listbox'};
            app.connsListBox.ValueChangedFcn = createCallbackFcn(app, @connsListBox_Callback, true);
            app.connsListBox.Tag = 'connsListBox';
            app.connsListBox.Value = 'Listbox';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.addConnButton = uibutton(app.GridLayout3, 'push');
            app.addConnButton.ButtonPushedFcn = createCallbackFcn(app, @addConnButton_Callback, true);
            app.addConnButton.Tag = 'addConnButton';
            app.addConnButton.Icon = 'add.png';
            app.addConnButton.Text = 'Add Connection';
            app.removeConnButton = uibutton(app.GridLayout3, 'push');
            app.removeConnButton.ButtonPushedFcn = createCallbackFcn(app, @removeConnButton_Callback, true);
            app.removeConnButton.Tag = 'removeConnButton';
            app.removeConnButton.Icon = 'remove.png';
            app.removeConnButton.Text = 'Remove Connection';
            % Show the figure after all components are created
            app.lvd_EditTankToTanksConnsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditTankToTankConnsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditTankToTanksConnsGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditTankToTankConnsGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditTankToTanksConnsGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditTankToTanksConnsGUI)
        end
    end
end