classdef lvd_EditTanksGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditTanksGUI        matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        uipanel1                matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        GridLayout3             matlab.ui.container.GridLayout
        removeTankButton        matlab.ui.control.Button
        addTankButton           matlab.ui.control.Button
        tanksListBox            matlab.ui.control.ListBox
        closeButton             matlab.ui.control.Button
        text2                   matlab.ui.control.Label
        tankListboxContextMenu  matlab.ui.container.ContextMenu
        copyTankContextMenu     matlab.ui.container.Menu
    end

    methods (Access = private)
        function stageState = getStageStateForTank(~, tank, lvdData)
            stage = tank.stage;
            stageStates = lvdData.initStateModel.stageStates;
            stageStateInd = find([stageStates.stage] == stage,1,'first');
            stageState = stageStates(stageStateInd);
        end

        function populateGUI(app, handles, ~)
            [~, tanks] = app.setListboxItems();

            numTanks = numel(tanks);
            if(numTanks <= 1)
                handles.removeTankButton.Enable = 'off';
            end
        end
        
        function [tanksListStr, tanks] = setListboxItems(app)
            lvdData = getappdata(app.lvd_EditTanksGUI,'lvdData');
            [tanksListStr, tanks] = lvdData.launchVehicle.getTanksListBoxStr();
            app.tanksListBox.Items = tanksListStr;
            app.tanksListBox.ItemsData = tanks;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditTanksGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditTanksGUI (see VARARGIN)

            % Choose default command line output for lvd_EditTanksGUI
            handles.output = hObject;

            centerUIFigure(app.lvd_EditTanksGUI);
            applySelectedThemeToApp(app);

            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            populateGUI(app, handles, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditTanksGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditTanksGUI);
        end

        % Button pushed function: addTankButton
        function addTankButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addTankButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditTanksGUI,'lvdData');

            initStage = lvdData.launchVehicle.getStageForInd(1);

            tank = LaunchVehicleTank(initStage);
            
            output = AppDesignerGUIOutput({false});
            lvd_EditTankGUI_App(tank, output);
            useTank = output.output{1};
            
            if(useTank)
                tank.stage.addTank(tank);

                stageStates = lvdData.initStateModel.stageStates;
                stageStateInd = find([stageStates.stage] == tank.stage,1,'first');
                stageState = stageStates(stageStateInd);

                newTankState = LaunchVehicleTankState(stageState);
                newTankState.tank = tank;
                newTankState.tankMass = tank.initialMass;
                stageState.addTankState(newTankState);

                [~, ~] = app.setListboxItems();
                app.tanksListBox.Value = tank;

                handles.removeTankButton.Enable = 'on';
            end
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to closeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditTanksGUI);
        end

        % Menu selected function: copyTankContextMenu
        function copyTankContextMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to copyTankContextMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditTanksGUI,'lvdData');
%             lv = lvdData.launchVehicle;

%             selTank = get(handles.tanksListBox,'Value');
%             tank = lv.getTankForInd(selTank);
            tank = app.tanksListBox.Value;

            newTank = tank.copy();
            initStage = newTank.stage;

            newTank.stage.addTank(newTank);

            stageStates = lvdData.initStateModel.stageStates;
            stageStateInd = find([stageStates.stage] == initStage,1,'first');
            stageState = stageStates(stageStateInd);

            newTankState = LaunchVehicleTankState(stageState);
            newTankState.tank = newTank;
            newTankState.tankMass = newTank.initialMass;
            stageState.addTankState(newTankState);

            [~, ~] = app.setListboxItems();

            handles.removeTankButton.Enable = 'on';
        end

        % Window key press function: lvd_EditTanksGUI
        function lvd_EditTanksGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditTanksGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            switch(eventdata.Key)
                case 'return'
                    close(handles.lvd_EditTanksGUI);
                case 'enter'
                    close(handles.lvd_EditTanksGUI);
                case 'escape'
                    close(handles.lvd_EditTanksGUI);
            end
        end

        % Button pushed function: removeTankButton
        function removeTankButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeTankButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditTanksGUI,'lvdData');
            lv = lvdData.launchVehicle;

%             selTank = get(handles.tanksListBox,'Value');
%             tank = lv.getTankForInd(selTank);
            tank = app.tanksListBox.Value;

            tf = tank.isInUse();

            if(tf == false)
                stage = tank.stage;

                stageStates = lvdData.initStateModel.stageStates;
                stageStateInd = find([stageStates.stage] == stage,1,'first');
                stageState = stageStates(stageStateInd);

                stageState.removeTankStateForTank(tank);

                stage.removeTank(tank);

                lv.removeAllEngineToTanksConnsWithTank(tank);

                [~, tanks] = app.setListboxItems();
                numTanks = length(tanks);

                if(numTanks <= 1)
                    handles.removeTankButton.Enable = 'off';
                end
            else
                msg = sprintf('Could not delete the tank "%s" because it is in use as part of an event termination condition, event action, objective function, or constraint.  Remove the tank dependencies before attempting to delete the engine.', tank.name);
%                 warndlg(msg,'Cannot Delete Tank','modal');
                uialert(app.lvd_EditTanksGUI, msg, 'Cannot Delete Tank',"Modal",true);
            end
        end

        % Value changed function: tanksListBox
        function tanksListBox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to tanksListBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns tanksListBox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from tanksListBox
            %                 if(strcmpi(get(handles.lvd_EditTanksGUI,'SelectionType'),'open'))
            %                     lvdData = getappdata(handles.lvd_EditTanksGUI,'lvdData');
            %                     lv = lvdData.launchVehicle;
            %
            %                     selTank = get(handles.tanksListBox,'Value');
            %                     tank = lv.getTankForInd(selTank);
            %                     stageState = getStageStateForTank(app, tank, lvdData);
            %                     stageState.removeTankStateForTank(tank);
            %
            %                     lvd_EditTankGUI(tank);
            %
            %                     stageState = getStageStateForTank(app, tank, lvdData);
            %                     newTankState = LaunchVehicleTankState(stageState);
            %                     newTankState.tank = tank;
            %                     stageState.addTankState(newTankState);
            %                     stageState.updateTankStateMassForTank(tank);
            %
            %                     set(handles.tanksListBox,'String',lvdData.launchVehicle.getTanksListBoxStr());
            %                 end
        end

        % Window button down function: lvd_EditTanksGUI
        function lvd_EditTanksGUIWindowButtonDown(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            selType = app.lvd_EditTanksGUI.SelectionType;
            curObj = app.lvd_EditTanksGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.tanksListBox && numel(app.tanksListBox.ItemsData) > 0)
                    lvdData = getappdata(handles.lvd_EditTanksGUI,'lvdData');
%                     lv = lvdData.launchVehicle;

%                     selTank = get(handles.tanksListBox,'Value');
%                     tank = lv.getTankForInd(selTank);
                    tank = app.tanksListBox.Value;
                    
                    stageState = getStageStateForTank(app, tank, lvdData);
                    stageState.removeTankStateForTank(tank);

                    output = AppDesignerGUIOutput({false});
                    lvd_EditTankGUI_App(tank, output);

                    stageState = getStageStateForTank(app, tank, lvdData);
                    newTankState = LaunchVehicleTankState(stageState);
                    newTankState.tank = tank;
                    stageState.addTankState(newTankState);
                    stageState.updateTankStateMassForTank(tank);

                    [~, ~] = app.setListboxItems();
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditTanksGUI = uifigure('Visible', 'off');
            app.lvd_EditTanksGUI.Name = 'Edit Tanks';
            app.lvd_EditTanksGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditTanksGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_EditTanksGUIWindowButtonDown, true);
            app.lvd_EditTanksGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditTanksGUI_WindowKeyPressFcn, true);
            app.lvd_EditTanksGUI.Tag = 'lvd_EditTanksGUI';
            app.GridLayout = uigridlayout(app.lvd_EditTanksGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Tanks';
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Icon = 'close_window(1).png';
            app.closeButton.Text = 'Close';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout2 = uigridlayout(app.uipanel1);
            app.tanksListBox = uilistbox(app.GridLayout2);
            app.tanksListBox.Items = {'Listbox'};
            app.tanksListBox.ValueChangedFcn = createCallbackFcn(app, @tanksListBox_Callback, true);
            app.tanksListBox.Tag = 'tanksListBox';
            app.tanksListBox.Value = 'Listbox';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.addTankButton = uibutton(app.GridLayout3, 'push');
            app.addTankButton.ButtonPushedFcn = createCallbackFcn(app, @addTankButton_Callback, true);
            app.addTankButton.Tag = 'addTankButton';
            app.addTankButton.Icon = 'add.png';
            app.addTankButton.Text = 'Add Tank';
            app.removeTankButton = uibutton(app.GridLayout3, 'push');
            app.removeTankButton.ButtonPushedFcn = createCallbackFcn(app, @removeTankButton_Callback, true);
            app.removeTankButton.Tag = 'removeTankButton';
            app.removeTankButton.Icon = 'remove.png';
            app.removeTankButton.Text = 'Remove Tank';
            app.tankListboxContextMenu = uicontextmenu(app.lvd_EditTanksGUI);
            app.tankListboxContextMenu.Tag = 'tankListboxContextMenu';
            app.copyTankContextMenu = uimenu(app.tankListboxContextMenu);
            app.copyTankContextMenu.MenuSelectedFcn = createCallbackFcn(app, @copyTankContextMenu_Callback, true);
            app.copyTankContextMenu.Text = 'Create Copy of Selected Tank';
            app.copyTankContextMenu.Tag = 'copyTankContextMenu';
            % Assign app.tankListboxContextMenu
            app.tanksListBox.ContextMenu = app.tankListboxContextMenu;
            % Show the figure after all components are created
            app.lvd_EditTanksGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditTanksGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditTanksGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditTanksGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditTanksGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditTanksGUI)
        end
    end
end