classdef lvd_editLaunchVehicle_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_editLaunchVehicle        matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout2                  matlab.ui.container.GridLayout
        uipanel2                     matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        lvSummaryText                matlab.ui.control.TextArea
        uipanel1                     matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        ImportVehiclefromButton      matlab.ui.control.Button
        editTankFluidTypesButton     matlab.ui.control.Button
        editEpsSinksButton           matlab.ui.control.Button
        editEpsStorageButton         matlab.ui.control.Button
        editEpsSrcsButton            matlab.ui.control.Button
        editTankToTankConnsButton    matlab.ui.control.Button
        editEngineToTankConnsButton  matlab.ui.control.Button
        editTanksButton              matlab.ui.control.Button
        editEnginesButton            matlab.ui.control.Button
        editStagesButton             matlab.ui.control.Button
        closeButton                  matlab.ui.control.Button
        text2                        matlab.ui.control.Label
    end

    
    methods (Access = private)
        function populateGUI(app, handles, lvdData)
%                 jScrollPane = findjobj(handles.lvSummaryText);
%                 jViewPort = jScrollPane.getViewport;
%                 jEditbox = jViewPort.getComponent(0);
%                 jEditbox.setWrapping(false);
%                 jScrollPane.setHorizontalScrollBarPolicy(32);
%                 jEditbox.setEditable(false);
%                 jEditbox.revalidate();
                setSummText(app, handles.lvSummaryText, lvdData.launchVehicle);
        end
        
        function setSummText(app, hSummText, lv)
                set(hSummText, 'String', lv.getLvSummaryStr());
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_editLaunchVehicle_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_editLaunchVehicle (see VARARGIN)
            
            % Choose default command line output for lvd_editLaunchVehicle
                handles.output = hObject;
                
                centerUIFigure(app.lvd_editLaunchVehicle);
                applySelectedThemeToApp(app);
            
                lvdData = varargin{1};
                setappdata(hObject, 'lvdData', lvdData);
            
                populateGUI(app, handles, lvdData);
            
                % Update handles structure
                guidata(hObject, handles);
            
                % UIWAIT makes lvd_editLaunchVehicle wait for user response (see UIRESUME)
                uiwait(handles.lvd_editLaunchVehicle);
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to closeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                close(handles.lvd_editLaunchVehicle);
        end

        % Button pushed function: editEngineToTankConnsButton
        function editEngineToTankConnsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editEngineToTankConnsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.lvd_editLaunchVehicle, 'lvdData');
                lvd_EditEngineToTanksConnsGUI_App(lvdData);
                setSummText(app, handles.lvSummaryText, lvdData.launchVehicle);
        end

        % Button pushed function: editEnginesButton
        function editEnginesButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editEnginesButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.lvd_editLaunchVehicle, 'lvdData');
                lvd_EditEnginesGUI_App(lvdData);
                setSummText(app, handles.lvSummaryText, lvdData.launchVehicle);
        end

        % Button pushed function: editEpsSinksButton
        function editEpsSinksButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editEpsSinksButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.lvd_editLaunchVehicle, 'lvdData');
                lvd_EditElectricalPowerSinksGUI_App(lvdData);
                setSummText(app, handles.lvSummaryText, lvdData.launchVehicle);
        end

        % Button pushed function: editEpsSrcsButton
        function editEpsSrcsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editEpsSrcsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.lvd_editLaunchVehicle, 'lvdData');
                lvd_EditElectricalPowerSrcsGUI_App(lvdData);
                setSummText(app, handles.lvSummaryText, lvdData.launchVehicle);
        end

        % Button pushed function: editEpsStorageButton
        function editEpsStorageButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editEpsStorageButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.lvd_editLaunchVehicle, 'lvdData');
                lvd_EditElectricalPowerStoragesGUI_App(lvdData);
                setSummText(app, handles.lvSummaryText, lvdData.launchVehicle);
        end

        % Button pushed function: editStagesButton
        function editStagesButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editStagesButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.lvd_editLaunchVehicle, 'lvdData');
                lvd_EditStagesGUI_App(lvdData);
                setSummText(app, handles.lvSummaryText, lvdData.launchVehicle);
        end

        % Button pushed function: editTankFluidTypesButton
        function editTankFluidTypesButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editTankFluidTypesButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.lvd_editLaunchVehicle, 'lvdData');
                lvd_EditTankFluidTypesGUI_App(lvdData);
                setSummText(app, handles.lvSummaryText, lvdData.launchVehicle);
        end

        % Button pushed function: editTankToTankConnsButton
        function editTankToTankConnsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editTankToTankConnsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.lvd_editLaunchVehicle, 'lvdData');
                lvd_EditTankToTankConnsGUI_App(lvdData);
                setSummText(app, handles.lvSummaryText, lvdData.launchVehicle);
        end

        % Button pushed function: editTanksButton
        function editTanksButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editTanksButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.lvd_editLaunchVehicle, 'lvdData');
                lvd_EditTanksGUI_App(lvdData);
                setSummText(app, handles.lvSummaryText, lvdData.launchVehicle);
        end

        % Window key press function: lvd_editLaunchVehicle
        function lvd_editLaunchVehicle_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to lvd_editLaunchVehicle (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
                switch(eventdata.Key)
                    case 'return'
                        uiresume(handles.lvd_editLaunchVehicle);
                    case 'enter'
                        uiresume(handles.lvd_editLaunchVehicle);
                    case 'escape'
                        uiresume(handles.lvd_editLaunchVehicle);
                end
        end

        % Close request function: lvd_editLaunchVehicle
        function lvd_editLaunchVehicleCloseRequest(app, event)
            lvdData = getappdata(app.lvd_editLaunchVehicle, 'lvdData');
            lvdData.initStateModel.clearAllTankStatesAndRegenerate();
            lvdData.initStateModel.clearDuplicateEngineStates();
            
            delete(app)
        end

        % Button pushed function: ImportVehiclefromButton
        function ImportVehiclefromButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            lvdData = getappdata(handles.lvd_editLaunchVehicle, 'lvdData');
            lvd_ImportCraftGUI_App(lvdData);
            setSummText(app, handles.lvSummaryText, lvdData.launchVehicle);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_editLaunchVehicle = uifigure('Visible', 'off');
            app.lvd_editLaunchVehicle.Name = 'Edit Vehicle Configuration';
            app.lvd_editLaunchVehicle.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_editLaunchVehicle.CloseRequestFcn = createCallbackFcn(app, @lvd_editLaunchVehicleCloseRequest, true);
            app.lvd_editLaunchVehicle.WindowKeyPressFcn = createCallbackFcn(app, @lvd_editLaunchVehicle_WindowKeyPressFcn, true);
            app.lvd_editLaunchVehicle.Tag = 'lvd_editLaunchVehicle';
            app.GridLayout = uigridlayout(app.lvd_editLaunchVehicle);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Vehicle Configuration';
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Icon = 'close_window(1).png';
            app.closeButton.Text = 'Close';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.uipanel1 = uipanel(app.GridLayout2);
            app.uipanel1.Title = 'Edit Launch Vehicle Elements';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout4 = uigridlayout(app.uipanel1);
            app.editStagesButton = uibutton(app.GridLayout4, 'push');
            app.editStagesButton.ButtonPushedFcn = createCallbackFcn(app, @editStagesButton_Callback, true);
            app.editStagesButton.Tag = 'editStagesButton';
            app.editStagesButton.Icon = 'rocket_booster.png';
            app.editStagesButton.Text = 'Edit Stages';
            app.editEnginesButton = uibutton(app.GridLayout4, 'push');
            app.editEnginesButton.ButtonPushedFcn = createCallbackFcn(app, @editEnginesButton_Callback, true);
            app.editEnginesButton.Tag = 'editEnginesButton';
            app.editEnginesButton.Icon = 'rocket_engine.png';
            app.editEnginesButton.Text = 'Edit Engines';
            app.editTanksButton = uibutton(app.GridLayout4, 'push');
            app.editTanksButton.ButtonPushedFcn = createCallbackFcn(app, @editTanksButton_Callback, true);
            app.editTanksButton.Tag = 'editTanksButton';
            app.editTanksButton.Icon = 'gas-cylinder.png';
            app.editTanksButton.Text = 'Edit Tanks';
            app.editEngineToTankConnsButton = uibutton(app.GridLayout4, 'push');
            app.editEngineToTankConnsButton.ButtonPushedFcn = createCallbackFcn(app, @editEngineToTankConnsButton_Callback, true);
            app.editEngineToTankConnsButton.Tag = 'editEngineToTankConnsButton';
            app.editEngineToTankConnsButton.Icon = 'connect_engine_tank.png';
            app.editEngineToTankConnsButton.Text = 'Edit Engine to Tank Connections';
            app.editTankToTankConnsButton = uibutton(app.GridLayout4, 'push');
            app.editTankToTankConnsButton.ButtonPushedFcn = createCallbackFcn(app, @editTankToTankConnsButton_Callback, true);
            app.editTankToTankConnsButton.Tag = 'editTankToTankConnsButton';
            app.editTankToTankConnsButton.Icon = 'connect_tank_tank.png';
            app.editTankToTankConnsButton.Text = 'Edit Tank to Tank Connections';
            app.editEpsSrcsButton = uibutton(app.GridLayout4, 'push');
            app.editEpsSrcsButton.ButtonPushedFcn = createCallbackFcn(app, @editEpsSrcsButton_Callback, true);
            app.editEpsSrcsButton.Tag = 'editEpsSrcsButton';
            app.editEpsSrcsButton.Icon = 'solar-panel.png';
            app.editEpsSrcsButton.Tooltip = 'Add or edit electrical power sources, including solar panels and RTGs.';
            app.editEpsSrcsButton.Text = 'Edit Electrical Power Sources';
            app.editEpsStorageButton = uibutton(app.GridLayout4, 'push');
            app.editEpsStorageButton.ButtonPushedFcn = createCallbackFcn(app, @editEpsStorageButton_Callback, true);
            app.editEpsStorageButton.Tag = 'editEpsStorageButton';
            app.editEpsStorageButton.Icon = 'battery.png';
            app.editEpsStorageButton.Tooltip = 'Add or edit electrical power storage (batteries).';
            app.editEpsStorageButton.Text = 'Edit Electrical Power Storage';
            app.editEpsSinksButton = uibutton(app.GridLayout4, 'push');
            app.editEpsSinksButton.ButtonPushedFcn = createCallbackFcn(app, @editEpsSinksButton_Callback, true);
            app.editEpsSinksButton.Tag = 'editEpsSinksButton';
            app.editEpsSinksButton.Icon = 'idea.png';
            app.editEpsSinksButton.Tooltip = 'Add or edit electrical power sinks: devices which consume electrical power from batteries.';
            app.editEpsSinksButton.Text = 'Edit Electrical Power Sinks';
            app.editTankFluidTypesButton = uibutton(app.GridLayout4, 'push');
            app.editTankFluidTypesButton.ButtonPushedFcn = createCallbackFcn(app, @editTankFluidTypesButton_Callback, true);
            app.editTankFluidTypesButton.Tag = 'editTankFluidTypesButton';
            app.editTankFluidTypesButton.Icon = 'fluid.png';
            app.editTankFluidTypesButton.Tooltip = 'Edits the fluid types that are available for each tank.';
            app.editTankFluidTypesButton.Text = 'Edit Tank Fluid Types';
            app.ImportVehiclefromButton = uibutton(app.GridLayout4, 'push');
            app.ImportVehiclefromButton.ButtonPushedFcn = createCallbackFcn(app, @ImportVehiclefromButtonPushed, true);
            app.ImportVehiclefromButton.Icon = 'import_craft.png';
            app.ImportVehiclefromButton.Text = 'Import Vehicle from Craft File';
            app.uipanel2 = uipanel(app.GridLayout2);
            app.uipanel2.Title = 'Launch Vehicle Summary';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout3 = uigridlayout(app.uipanel2);
            app.lvSummaryText = uitextarea(app.GridLayout3);
            app.lvSummaryText.Tag = 'lvSummaryText';
            app.lvSummaryText.Editable = 'off';
            app.lvSummaryText.Value = {'Edit Text'};
            % Show the figure after all components are created
            app.lvd_editLaunchVehicle.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_editLaunchVehicle_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_editLaunchVehicle)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_editLaunchVehicle_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_editLaunchVehicle)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_editLaunchVehicle)
        end
    end
end