classdef lvd_editThrottleModifierProfileElementGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_editThrottleModifierProfileElementGUI  matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        uipanel1            matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        depUnitLbl          matlab.ui.control.Label
        throtModifierText   matlab.ui.control.EditField
        depValLabel         matlab.ui.control.Label
        indepUnitLbl        matlab.ui.control.Label
        fuRemainPctText     matlab.ui.control.EditField
        indepValLabel       matlab.ui.control.Label
        GridLayout2         matlab.ui.container.GridLayout
        cancelButton        matlab.ui.control.Button
        saveAndCloseButton  matlab.ui.control.Button
        titleLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateUI(app, elem, allowFuPctEdit, curve, handles)
            handles.titleLabel.String = sprintf('Edit %s Element', curve.getCurveName());

            handles.indepValLabel.String = elem.getIndepVarName();
            handles.fuRemainPctText.String = fullAccNum2Str(elem.indepVar);
            handles.indepUnitLbl.String = elem.getIndepVarUnit();

            handles.depValLabel.String = elem.getDepVarName();
            handles.throtModifierText.String = fullAccNum2Str(elem.depVar);
            handles.depUnitLbl.String = elem.getDepVarUnit();

            if(allowFuPctEdit == false)
                handles.fuRemainPctText.Enable = 'off';
            end
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            elem = getappdata(handles.lvd_editThrottleModifierProfileElementGUI,'elem');

            val = str2double(get(handles.fuRemainPctText,'String'));
            enteredStr = get(handles.fuRemainPctText,'String');
            numberName = elem.getIndepVarName();
            lb = elem.minIndepValue;
            ub = elem.maxIndepValue;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                if((val == elem.minIndepValue || val == elem.maxIndepValue) && elem.indepVar ~= val)
                    errMsg = {sprintf('Cannot add points at %.1f %s or %.1f %s.  Edit these points from the existing list if necessary.', elem.minIndepValue, elem.getIndepVarUnit(), elem.maxIndepValue, elem.getIndepVarUnit())};
                end
            end

            val = str2double(get(handles.throtModifierText,'String'));
            enteredStr = get(handles.throtModifierText,'String');
            numberName = elem.getDepVarName();
            lb = elem.minDepValue;
            ub = elem.maxDepValue;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
        end

        
        function saveAndClose(app, handles)
            elem = getappdata(handles.lvd_editThrottleModifierProfileElementGUI,'elem');
            elem.indepVar = str2double(handles.fuRemainPctText.String);
            elem.depVar = str2double(handles.throtModifierText.String);
            
            app.output.output = {true};
            close(handles.lvd_editThrottleModifierProfileElementGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_editThrottleModifierProfileElementGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_editThrottleModifierProfileElementGUI (see VARARGIN)

            % Choose default command line output for lvd_editThrottleModifierProfileElementGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            elem = varargin{1};
            setappdata(hObject,'elem',elem);

            allowFuPctEdit = varargin{2};
            setappdata(hObject,'allowFuPctEdit',allowFuPctEdit);

            curve = varargin{3};
            setappdata(hObject,'curve',curve);
            
            app.output = varargin{4};

            % Update handles structure
            guidata(hObject, handles);

            populateUI(app, elem, allowFuPctEdit, curve, handles);

            % UIWAIT makes lvd_editThrottleModifierProfileElementGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_editThrottleModifierProfileElementGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_editThrottleModifierProfileElementGUI);
        end

        % Value changed function: fuRemainPctText
        function fuRemainPctText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to fuRemainPctText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of fuRemainPctText as text
            %        str2double(get(hObject,'String')) returns contents of fuRemainPctText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Window key press function: 
        % lvd_editThrottleModifierProfileElementGUI
        function lvd_editThrottleModifierProfileElementGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_editThrottleModifierProfileElementGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_editThrottleModifierProfileElementGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
%                 uiresume(handles.lvd_editThrottleModifierProfileElementGUI);
                app.saveAndClose(handles);
            else
%                 msgbox(errMsg,'Invalid Inputs','error');
                uialert(app.lvd_editThrottleModifierProfileElementGUI, errMsg, 'Invalid Inputs', "Icon","error");
            end
        end

        % Value changed function: throtModifierText
        function throtModifierText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to throtModifierText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of throtModifierText as text
            %        str2double(get(hObject,'String')) returns contents of throtModifierText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_editThrottleModifierProfileElementGUI = uifigure('Visible', 'off');
            app.lvd_editThrottleModifierProfileElementGUI.Name = 'Edit Throttle Modifier Profile Element';
            app.lvd_editThrottleModifierProfileElementGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_editThrottleModifierProfileElementGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_editThrottleModifierProfileElementGUI_WindowKeyPressFcn, true);
            app.lvd_editThrottleModifierProfileElementGUI.Tag = 'lvd_editThrottleModifierProfileElementGUI';
            app.GridLayout = uigridlayout(app.lvd_editThrottleModifierProfileElementGUI);
            app.titleLabel = uilabel(app.GridLayout);
            app.titleLabel.Tag = 'titleLabel';
            app.titleLabel.Text = 'Edit Throttle Modifier Profile Element';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.indepValLabel = uilabel(app.GridLayout3);
            app.indepValLabel.Tag = 'indepValLabel';
            app.indepValLabel.Text = 'Pct Fuel Remaining';
            app.fuRemainPctText = uieditfield(app.GridLayout3, 'text');
            app.fuRemainPctText.ValueChangedFcn = createCallbackFcn(app, @fuRemainPctText_Callback, true);
            app.fuRemainPctText.Tag = 'fuRemainPctText';
            app.fuRemainPctText.Tooltip = 'The amount of fuel remaining across all tanks on active stages connected to this engine.  Expressed as a percent of the initial load of the tanks.  Must be within 0 to 100.';
            app.fuRemainPctText.Value = '1';
            app.indepUnitLbl = uilabel(app.GridLayout3);
            app.indepUnitLbl.Tag = 'indepUnitLbl';
            app.indepUnitLbl.Text = '%';
            app.depValLabel = uilabel(app.GridLayout3);
            app.depValLabel.Tag = 'depValLabel';
            app.depValLabel.Text = 'Throttle Modifier';
            app.throtModifierText = uieditfield(app.GridLayout3, 'text');
            app.throtModifierText.ValueChangedFcn = createCallbackFcn(app, @throtModifierText_Callback, true);
            app.throtModifierText.Tag = 'throtModifierText';
            app.throtModifierText.Tooltip = 'The multipler, from 0.0 to 1.0, applied to this engine at the specified fuel remaining percentage above.  Applied after the min and max throttle settings.';
            app.throtModifierText.Value = '1';
            app.depUnitLbl = uilabel(app.GridLayout3);
            app.depUnitLbl.Tag = 'depUnitLbl';
            app.depUnitLbl.Text = '';
            % Show the figure after all components are created
            app.lvd_editThrottleModifierProfileElementGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_editThrottleModifierProfileElementGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_editThrottleModifierProfileElementGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_editThrottleModifierProfileElementGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_editThrottleModifierProfileElementGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_editThrottleModifierProfileElementGUI)
        end
    end
end