classdef lvd_editEventGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_editEventGUI                matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        text2                           matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        prevEventButton                 matlab.ui.control.Button
        nextEventButton                 matlab.ui.control.Button
        saveAndCloseButton              matlab.ui.control.Button
        EventTabGroup                   matlab.ui.container.TabGroup
        EventTab                        matlab.ui.container.Tab
        EventTabGrid                    matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        text3                           matlab.ui.control.Label
        eventNameText                   matlab.ui.control.EditField
        GridLayout5                     matlab.ui.container.GridLayout
        lineSpecCombo                   matlab.ui.control.DropDown
        text9                           matlab.ui.control.Label
        lineWidthCombo                  matlab.ui.control.DropDown
        Image2                          matlab.ui.control.Image
        GridLayout13                    matlab.ui.container.GridLayout
        markerTypeCombo                 matlab.ui.control.DropDown
        text9_2                         matlab.ui.control.Label
        markerSizeCombo                 matlab.ui.control.DropDown
        Image3                          matlab.ui.control.Image
        GridLayout14                    matlab.ui.container.GridLayout
        colorSpecCombo                  matlab.ui.control.DropDown
        Image                           matlab.ui.control.Image
        GridLayout15                    matlab.ui.container.GridLayout
        plotMethodCombo                 matlab.ui.control.DropDown
        Image4                          matlab.ui.control.Image
        TerminationConditionPanel       matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        termCondListbox                 matlab.ui.control.ListBox
        TermCondButtonGrid              matlab.ui.container.GridLayout
        editTermCondParamsButton        matlab.ui.control.Button
        addTermCondButton               matlab.ui.control.Button
        removeTermCondButton            matlab.ui.control.Button
        TermCondDirGrid                 matlab.ui.container.GridLayout
        eventTermCondDirCombo           matlab.ui.control.DropDown
        termCondDirLabel                matlab.ui.control.Label
        TermCondLogicGrid               matlab.ui.container.GridLayout
        termCondLogicLabel              matlab.ui.control.Label
        termCondLogicCombo              matlab.ui.control.DropDown
        GridLayout7                     matlab.ui.container.GridLayout
        ActionsPanel                    matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        actionsListbox                  matlab.ui.control.ListBox
        actionExecNodeCombo             matlab.ui.control.DropDown
        GridLayout9                     matlab.ui.container.GridLayout
        addActionButton                 matlab.ui.control.Button
        removeActionButton              matlab.ui.control.Button
        EventSpecificPropagatorSettingsPanel  matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        checkSoITransCheckbox           matlab.ui.control.CheckBox
        propDirCombo                    matlab.ui.control.DropDown
        integratorDetailLabel           matlab.ui.control.Label
        propagatorDetailLabel           matlab.ui.control.Label
        GridLayout11                    matlab.ui.container.GridLayout
        propagatorOptionsButton         matlab.ui.control.Button
        propagatorCombo                 matlab.ui.control.DropDown
        GridLayout12                    matlab.ui.container.GridLayout
        integratorOptionsButton         matlab.ui.control.Button
        integratorCombo                 matlab.ui.control.DropDown
        AdvancedTab                     matlab.ui.container.Tab
        AdvancedTabGrid                 matlab.ui.container.GridLayout
        GroupNotesPanel                 matlab.ui.container.Panel
        GroupNotesGrid                  matlab.ui.container.GridLayout
        groupNameLabel                  matlab.ui.control.Label
        groupNameCombo                  matlab.ui.control.DropDown
        notesLabel                      matlab.ui.control.Label
        notesTextArea                   matlab.ui.control.TextArea
        tagsLabel                       matlab.ui.control.Label
        eventTagsText                   matlab.ui.control.EditField
        EventLimitsPanel                matlab.ui.container.Panel
        EventLimitsGrid                 matlab.ui.container.GridLayout
        useMinAltCheckbox               matlab.ui.control.CheckBox
        minAltField                     matlab.ui.control.NumericEditField
        minAltUnitLabel                 matlab.ui.control.Label
        terrainRelCheckbox              matlab.ui.control.CheckBox
        useMaxDurCheckbox               matlab.ui.control.CheckBox
        maxDurField                     matlab.ui.control.NumericEditField
        maxDurUnitLabel                 matlab.ui.control.Label
        NonSeqPanel                     matlab.ui.container.Panel
        NonSeqGrid                      matlab.ui.container.GridLayout
        nonSeqEnabledCheckbox           matlab.ui.control.CheckBox
        priorityLabel                   matlab.ui.control.Label
        nonSeqPriorityField             matlab.ui.control.NumericEditField
        nonSeqLogExecCheckbox           matlab.ui.control.CheckBox
        integratorContextMenu           matlab.ui.container.ContextMenu
        determineFastestIntegratorMenu  matlab.ui.container.Menu
        initialStepSizeContextMenu      matlab.ui.container.ContextMenu
        optimizeInitialStepSizeMenu     matlab.ui.container.Menu
    end

    properties (Access = private)
        output AppDesignerGUIOutput
    end
    methods (Access = private)
        function cancelButton_Callback(app, hObject, eventdata, handles) %#ok<INUSL> 
            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_editEventGUI);
        end

        function integratorObj = getSelectedEventIntegratorObj(app, handles)
            event = getappdata(handles.lvd_editEventGUI,'event');

            propagatorObj = app.getSelectedEventPropagatorObj(handles);

            contents = cellstr(get(handles.integratorCombo,'String'));
            nameStr = contents{get(handles.integratorCombo,'Value')};
            [~,m] = IntegratorEnum.getIndOfListboxStr(nameStr, propagatorObj.propagatorEnum.needsFirstOrderIntegrator, propagatorObj.propagatorEnum.needsSecondOrderIntegrator);

            if(isempty(m))
                [~,m] = IntegratorEnum.getListBoxStrs(propagatorObj.propagatorEnum.needsFirstOrderIntegrator, propagatorObj.propagatorEnum.needsSecondOrderIntegrator);
                m = m(1);
            end

            switch m
                case IntegratorEnum.ODE113
                    integratorObj = event.ode113Integrator;

                case IntegratorEnum.ODE15s
                    integratorObj = event.ode15sIntegrator;

                case IntegratorEnum.ODE23
                    integratorObj = event.ode23Integrator;

                case IntegratorEnum.ODE23s
                    integratorObj = event.ode23sIntegrator;

                case IntegratorEnum.ODE45
                    integratorObj = event.ode45Integrator;

                case IntegratorEnum.ODE78
                    integratorObj = event.ode78Integrator;

                case IntegratorEnum.ODE89
                    integratorObj = event.ode89Integrator;

                case IntegratorEnum.ODE5
                    integratorObj = event.ode5Integrator;

                case IntegratorEnum.RKN1210
                    integratorObj = event.rkn1210Integrator;

                otherwise
                    error('Unknown integrator type.');
            end
        end

        function propagatorObj = getSelectedEventPropagatorObj(app, handles) %#ok<INUSL> 
            event = getappdata(handles.lvd_editEventGUI,'event');

            contents = cellstr(get(handles.propagatorCombo,'String'));
            nameStr = contents{get(handles.propagatorCombo,'Value')};
            [~,m] = PropagatorEnum.getIndForName(nameStr);

            switch m
                case PropagatorEnum.ForceModel
                    propagatorObj = event.forceModelPropagator;

                case PropagatorEnum.TwoBody
                    propagatorObj = event.twoBodyPropagator;

                case PropagatorEnum.SecOrdGravOnly
                    propagatorObj = event.secOrdGravOnlyPropagator;

                otherwise
                    error('Unknown propagator type.');
            end
        end

        function loadNewEvent(app, newEvent, handles)
            hUiProg = uiprogressdlg(app.lvd_editEventGUI,"Indeterminate","on");
            
            saveEvent(app, handles);

            setappdata(handles.lvd_editEventGUI,'event',newEvent);
            newEvent.clearActiveOptVarsCache();

            isNonSeq = getappdata(handles.lvd_editEventGUI,'isNonSeq');
            populateGUI(app, handles, newEvent, isNonSeq);

            if(isvalid(hUiProg))
                close(hUiProg);
            end
        end

        function elapsedTime = optInitStepSizeObjFcn(app, x, event, initStateLogEntry, simDriver, tStartSimTime, isSparseOutput, activeNonSeqEvts) %#ok<INUSL> 
            integrator = event.integratorObj;
            propOptions = integrator.getOptions();
            propOptions.setIntegratorStepSize(x(1));

            tStartPropTime = tic();

            tt = tic;
            event.executeEvent(initStateLogEntry, simDriver, tStartPropTime, tStartSimTime, isSparseOutput, activeNonSeqEvts);
            elapsedTime = toc(tt);
        end

        function populateGUI(app, handles, event, isNonSeq)
            set(handles.eventNameText,'String',event.name);
            app.refreshTermCondList(event, 1);

            evtNum = event.getEventNum();
            if(not(isempty(evtNum)) && not(isnan(evtNum)))
                app.lvd_editEventGUI.Name = sprintf('Edit Event #%u', evtNum);
                app.prevEventButton.Enable = "on";
                app.nextEventButton.Enable = "on";
            else
                app.lvd_editEventGUI.Name = 'Edit Event';
                app.prevEventButton.Enable = "off";
                app.nextEventButton.Enable = "off";
            end


            [actionsListboxStr, actions] = event.getActionsListboxStr();
            app.actionsListbox.Items = actionsListboxStr;
            app.actionsListbox.ItemsData = actions;

            app.setupEventColorPicker(handles, event, isNonSeq);

            handles.lineSpecCombo.String = LineSpecEnum.getListboxStr();
            [~,ind] = LineSpecEnum.getEnumForListboxStr(event.colorLineSpec.lineSpec.name);
            handles.lineSpecCombo.Value = ind;

            [listboxStrMarker, mMarker] = MarkerStyleEnum.getListboxStr();
            app.markerTypeCombo.Items = listboxStrMarker;
            app.markerTypeCombo.ItemsData = mMarker;
            app.markerTypeCombo.Value = event.colorLineSpec.markerSpec;

            validMarkerSizes = 1:12;
            app.markerSizeCombo.Items = string(validMarkerSizes);
            app.markerSizeCombo.ItemsData = validMarkerSizes;
            app.markerSizeCombo.Value = event.colorLineSpec.markerSize;

            handles.plotMethodCombo.String = EventPlottingMethodEnum.getListBoxStr();
            ind = EventPlottingMethodEnum.getIndForName(event.plotMethod.name);
            handles.plotMethodCombo.Value = ind;


            contents = handles.lineWidthCombo.String;
            contentsDouble = str2double(contents);
            ind = find(contentsDouble == event.colorLineSpec.lineWidth);
            set(handles.lineWidthCombo,'Value',ind);

            handles.integratorCombo.String = IntegratorEnum.getListBoxStrs();
            ind = IntegratorEnum.getIndOfListboxStr(event.integratorObj.integratorEnum.nameStr);
            handles.integratorCombo.Value = ind;
            
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            integratorCombo_Callback(app, evt);

            handles.propagatorCombo.String = PropagatorEnum.getListBoxStr();
            ind = PropagatorEnum.getIndForName(event.propagatorObj.propagatorEnum.name);
            handles.propagatorCombo.Value = ind;
            
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            propagatorCombo_Callback(app, evt);

            handles.propDirCombo.String = PropagationDirectionEnum.getListBoxStr();
            handles.propDirCombo.Value = PropagationDirectionEnum.getIndForName(event.propDir.name);

            handles.checkSoITransCheckbox.Value = double(event.checkForSoITrans);

            handles.actionExecNodeCombo.String = ActionExecNodeEnum.getListBoxStr();
            handles.actionExecNodeCombo.Value = ActionExecNodeEnum.getIndForName(event.execActionsNode.name);

            %Advanced tab: condition logic, group and notes, per-event limits,
            %non-sequential settings
            app.termCondLogicCombo.Items = EventTermCondLogicEnum.getListboxStr();
            app.termCondLogicCombo.Value = event.termCondLogic.name;

            groupNames = {'<No Group>'};
            if(not(isempty(event.script)))
                groupNames = horzcat(groupNames, event.script.getGroupNames());
            end
            if(not(isempty(event.groupName)) && not(any(strcmp(groupNames, event.groupName))))
                groupNames{end+1} = event.groupName;
            end
            app.groupNameCombo.Items = groupNames;
            if(isempty(event.groupName))
                app.groupNameCombo.Value = '<No Group>';
            else
                app.groupNameCombo.Value = event.groupName;
            end
            LvdMarkdownNotesEditor.ensure(app.notesTextArea, char(event.notes)); app.eventTagsText.Value = event.tags;

            app.useMinAltCheckbox.Value = event.useEvtMinAltitude;
            app.minAltField.Value = event.evtMinAltitude;
            app.terrainRelCheckbox.Value = event.minAltIsTerrainRelative;
            app.useMaxDurCheckbox.Value = event.useEvtMaxDur;
            if(isfinite(event.evtMaxDur))
                app.maxDurField.Value = event.evtMaxDur;
            else
                app.maxDurField.Value = 0;
            end

            nonSeqEvt = app.getNonSeqEvtForEvent(event, isNonSeq);
            if(isempty(nonSeqEvt))
                app.NonSeqPanel.Visible = 'off';
                app.AdvancedTabGrid.RowHeight{3} = 0;
            else
                app.NonSeqPanel.Visible = 'on';
                app.nonSeqEnabledCheckbox.Value = nonSeqEvt.enabled;
                app.nonSeqPriorityField.Value = nonSeqEvt.priority;
                app.nonSeqLogExecCheckbox.Value = nonSeqEvt.logExecutions;

                %A non-sequential event fires on whichever of its conditions
                %crosses first; "All" latching only exists for sequential events.
                app.termCondLogicCombo.Value = EventTermCondLogicEnum.Any.name;
                app.termCondLogicCombo.Enable = 'off';
            end
            app.updateAdvancedEnableStates();

            if(isNonSeq)
                handles.lineSpecCombo.Enable = 'off';
                handles.lineWidthCombo.Enable = 'off';
                handles.plotMethodCombo.Enable = 'off';
                handles.propagatorCombo.Enable = 'off';
                handles.propagatorOptionsButton.Enable = 'off';
                handles.integratorCombo.Enable = 'off';
                handles.integratorOptionsButton.Enable = 'off';
                handles.propDirCombo.Enable = 'off';
                handles.checkSoITransCheckbox.Enable = 'off';
                handles.prevEventButton.Enable = 'off';
                handles.nextEventButton.Enable = 'off';
            end
        end

        function saveEvent(app, handles)
            event = getappdata(handles.lvd_editEventGUI,'event');

            evtName = get(handles.eventNameText,'String');
            event.name = evtName;

            event.colorLineSpec.color = app.getEventColorPickerRGB(handles, event);

            nameStr = handles.lineSpecCombo.String(handles.lineSpecCombo.Value);
            [enum,~] = LineSpecEnum.getEnumForListboxStr(nameStr);
            event.colorLineSpec.lineSpec = enum;

            event.colorLineSpec.markerSpec = app.markerTypeCombo.Value;
            event.colorLineSpec.markerSize = app.markerSizeCombo.Value;


            nameStr = handles.plotMethodCombo.String(handles.plotMethodCombo.Value);
            [enum,~] = EventPlottingMethodEnum.getEnumForListboxStr(nameStr);
            event.plotMethod = enum;

            contents = handles.lineWidthCombo.String;
            contentsDouble = str2double(contents);
            contensInd = get(handles.lineWidthCombo,'Value');
            lineWidth = contentsDouble(contensInd);
            event.colorLineSpec.lineWidth = lineWidth;

            integratorObj = getSelectedEventIntegratorObj(app, handles);
            event.integratorObj = integratorObj;

            propagatorObj = getSelectedEventPropagatorObj(app, handles);
            event.propagatorObj = propagatorObj;

            nameStr = handles.propDirCombo.String(handles.propDirCombo.Value);
            [enum,~] = PropagationDirectionEnum.getEnumForListboxStr(nameStr);
            event.propDir = enum;

            event.checkForSoITrans = logical(handles.checkSoITransCheckbox.Value);

            nameStr = handles.actionExecNodeCombo.String(handles.actionExecNodeCombo.Value);
            [enum,~] = ActionExecNodeEnum.getEnumForListboxStr(nameStr);
            event.execActionsNode = enum;

            event.termCondLogic = EventTermCondLogicEnum.getEnumForListboxStr(app.termCondLogicCombo.Value);

            groupName = strtrim(app.groupNameCombo.Value);
            if(strcmp(groupName, '<No Group>'))
                groupName = '';
            end
            event.groupName = groupName;
            event.notes = LvdMarkdownNotesEditor.getText(app.lvd_editEventGUI); event.tags = strtrim(app.eventTagsText.Value);

            event.useEvtMinAltitude = logical(app.useMinAltCheckbox.Value);
            event.evtMinAltitude = app.minAltField.Value;
            event.minAltIsTerrainRelative = logical(app.terrainRelCheckbox.Value);
            event.useEvtMaxDur = logical(app.useMaxDurCheckbox.Value);
            if(event.useEvtMaxDur)
                event.evtMaxDur = app.maxDurField.Value;
            end

            nonSeqEvt = getappdata(handles.lvd_editEventGUI, 'nonSeqEvt');
            if(not(isempty(nonSeqEvt)))
                nonSeqEvt.enabled = logical(app.nonSeqEnabledCheckbox.Value);
                nonSeqEvt.priority = app.nonSeqPriorityField.Value;
                nonSeqEvt.logExecutions = logical(app.nonSeqLogExecCheckbox.Value);
            end

            event.clearActiveOptVarsCache();
        end

        function refreshTermCondList(app, event, selInd)
            %refreshTermCondList Rebuilds the termination condition list and
            %shows the selected condition's crossing direction.
            if(nargin < 3 || isempty(selInd))
                selInd = app.termCondListbox.Value;
            end

            conds = event.getAllTermConds();
            dirs = event.getAllTermCondDirs();

            items = cell(1, numel(conds));
            for(i=1:numel(conds)) %#ok<*NO4LP>
                items{i} = sprintf('%u.  %s  [%s]', i, conds(i).getName(), dirs(i).name);
            end

            if(isempty(selInd))
                selInd = 1;
            end
            selInd = min(max(selInd, 1), numel(conds));

            app.termCondListbox.Items = items;
            app.termCondListbox.ItemsData = 1:numel(conds);
            app.termCondListbox.Value = selInd;

            app.eventTermCondDirCombo.Items = EventTermCondDirectionEnum.getListboxStr();
            app.eventTermCondDirCombo.Value = dirs(selInd).name;

            app.removeTermCondButton.Enable = matlab.lang.OnOffSwitchState(numel(conds) > 1);
        end

        function ind = getSelectedTermCondInd(app)
            ind = app.termCondListbox.Value;
            if(isempty(ind))
                ind = 1;
            end
        end

        function editSelectedTermCond(app, event)
            %editSelectedTermCond Opens the termination condition editor on
            %the selected condition.  lvd_editEvtTermCond_App edits
            %event.termCond, so the selected condition is swapped into that
            %slot for the duration of the editor and written back after.
            ind = app.getSelectedTermCondInd();
            conds = event.getAllTermConds();
            if(ind < 1 || ind > numel(conds))
                return;
            end

            savedCond = event.termCond;
            event.termCond = conds(ind);
            lvd_editEvtTermCond_App(event);
            editedCond = event.termCond;
            event.termCond = savedCond;
            event.setTermCondByInd(ind, editedCond);

            app.refreshTermCondList(event, ind);
        end

        function updateAdvancedEnableStates(app)
            app.minAltField.Enable = matlab.lang.OnOffSwitchState(app.useMinAltCheckbox.Value);
            app.terrainRelCheckbox.Enable = matlab.lang.OnOffSwitchState(app.useMinAltCheckbox.Value);
            app.maxDurField.Enable = matlab.lang.OnOffSwitchState(app.useMaxDurCheckbox.Value);
            app.nonSeqPriorityField.Enable = matlab.lang.OnOffSwitchState(app.nonSeqEnabledCheckbox.Value);
        end

        function nonSeqEvt = getNonSeqEvtForEvent(app, event, isNonSeq)
            %getNonSeqEvtForEvent The LaunchVehicleNonSeqEvent wrapping event,
            %or empty for a sequential event.  Kept in the figure's appdata
            %for saveEvent.
            nonSeqEvt = LaunchVehicleNonSeqEvent.empty(1,0);

            if(isNonSeq && not(isempty(event.script)) && not(isempty(event.script.nonSeqEvts)))
                allNonSeq = event.script.nonSeqEvts.nonSeqEvts;
                for(i=1:numel(allNonSeq))
                    if(allNonSeq(i).evt == event)
                        nonSeqEvt = allNonSeq(i);
                        break;
                    end
                end
            end

            setappdata(app.lvd_editEventGUI, 'nonSeqEvt', nonSeqEvt);
        end

        function str = joinTextAreaValue(~, value)
            if(iscell(value))
                str = char(strjoin(value(:)', newline));
            else
                str = char(value);
            end

            if(isempty(str))
                str = '';
            end
        end

        function errMsg = validateInputs(app, handles) %#ok<INUSD> 
            errMsg = {};

            if(app.useMinAltCheckbox.Value && not(isfinite(app.minAltField.Value)))
                errMsg{end+1} = 'The per-event minimum altitude must be a finite number of kilometers.';
            end

            if(app.useMaxDurCheckbox.Value && (not(isfinite(app.maxDurField.Value)) || app.maxDurField.Value <= 0))
                errMsg{end+1} = 'The per-event maximum duration must be a finite number of seconds greater than zero.';
            end

            if(strcmpi(app.NonSeqPanel.Visible, 'on') && not(isfinite(app.nonSeqPriorityField.Value)))
                errMsg{end+1} = 'The non-sequential event priority must be a finite number.';
            end
        end
        function setupEventColorPicker(app, handles, event, isNonSeq)
            %setupEventColorPicker Free RGB color button + swatch.
            %   Replaces the legacy fixed-list colorSpecCombo dropdown (which
            %   is deleted at run time) with a "Choose..." button that opens
            %   uisetcolor and a swatch panel previewing the working color.
            %   Idempotent: prev/next-event navigation calls populateGUI
            %   again, which only refreshes the swatch.
            fig = handles.lvd_editEventGUI;
            rgb = lvd_colorSpecToRGB(event.colorLineSpec.color);
            setappdata(fig, 'eventColorRGB', rgb);
            if(not(isappdata(fig, 'eventColorPickerFcn')))
                setappdata(fig, 'eventColorPickerFcn', @uisetcolor);
            end

            if(isvalid(app.colorSpecCombo))
                delete(app.colorSpecCombo);
            end

            grid = app.GridLayout14;
            grid.ColumnWidth = {20, '1x', 46};

            btn = getappdata(fig, 'eventColorButton');
            if(isempty(btn) || not(all(isvalid(btn))))
                btn = uibutton(grid, 'push');
                btn.Tag = 'eventColorButton';
                btn.Text = 'Choose...';
                btn.Tooltip = 'Pick any color for this event''s trajectory line. Markers use the same color.';
                btn.FontSize = 10.6666666666667;
                btn.ButtonPushedFcn = @(src, evt) app.eventColorButtonPushed(src, evt);
                btn.Layout.Row = 1;
                btn.Layout.Column = 2;
                setappdata(fig, 'eventColorButton', btn);
            end

            sw = getappdata(fig, 'eventColorSwatch');
            if(isempty(sw) || not(all(isvalid(sw))))
                sw = uipanel(grid);
                sw.Tag = 'eventColorSwatch';
                sw.Title = '';
                sw.BorderType = 'line';
                sw.Layout.Row = 1;
                sw.Layout.Column = 3;
                setappdata(fig, 'eventColorSwatch', sw);
            end
            sw.BackgroundColor = rgb;

            if(isNonSeq)
                btn.Enable = 'off';
            else
                btn.Enable = 'on';
            end
        end

        function eventColorButtonPushed(app, ~, ~)
            %eventColorButtonPushed Opens the system color picker.
            %   The picker is seam-injected via the eventColorPickerFcn
            %   appdata (default @uisetcolor) so UI tests can stub it.
            fig = app.lvd_editEventGUI;
            current = getappdata(fig, 'eventColorRGB');
            if(isempty(current))
                current = [1, 0, 0];
            end
            picker = getappdata(fig, 'eventColorPickerFcn');
            if(isempty(picker))
                picker = @uisetcolor;
            end
            try
                newColor = picker(current, 'Event Line Color');
            catch
                return;
            end
            if(isequal(newColor, 0) || not(isnumeric(newColor)) || numel(newColor) ~= 3)
                return;
            end
            newColor = double(reshape(newColor, 1, 3));
            if(any(not(isfinite(newColor))) || any(newColor < 0) || any(newColor > 1))
                return;
            end
            setappdata(fig, 'eventColorRGB', newColor);
            sw = getappdata(fig, 'eventColorSwatch');
            if(not(isempty(sw)) && all(isvalid(sw)))
                sw.BackgroundColor = newColor;
            end
            figure(fig);
        end

        function rgb = getEventColorPickerRGB(app, handles, event)
            %getEventColorPickerRGB Working color for saveEvent.
            %   Falls back to the event's stored color if the picker state
            %   is missing or invalid.
            rgb = getappdata(handles.lvd_editEventGUI, 'eventColorRGB');
            try
                rgb = lvd_colorSpecToRGB(rgb);
            catch
                rgb = lvd_colorSpecToRGB(event.colorLineSpec.color);
            end
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_editEventGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_editEventGUI (see VARARGIN)

            % Choose default command line output for lvd_editEventGUI
            handles.output = hObject;
            
            centerUIFigure(app.lvd_editEventGUI);
            applySelectedThemeToApp(app);

            event = varargin{1};
            setappdata(hObject,'event',event);
            event.clearActiveOptVarsCache();

            isNonSeq = varargin{2};
            setappdata(hObject,'isNonSeq',isNonSeq);
            
            app.output = varargin{3};

            populateGUI(app, handles, event, isNonSeq);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_editEventGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_editEventGUI);
        end

        % Value changed function: actionsListbox
        function actionsListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to actionsListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns actionsListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from actionsListbox
%             if(strcmpi(get(handles.lvd_editEventGUI,'SelectionType'),'open'))
%                 event = getappdata(handles.lvd_editEventGUI,'event');
%                 lv = event.script.lvdData.launchVehicle;
% 
%                 if(not(isempty(event.getActionsListboxStr())))
%                     selActionInd = get(handles.actionsListbox,'Value');
%                     action = event.getActionForInd(selActionInd);
% 
%                     action.event = event;
%                     action.openEditActionUI(action, lv);
% 
%                     actionsListBoxStr = event.getActionsListboxStr();
% 
%                     if(not(isempty(actionsListBoxStr)))
%                         set(handles.actionsListbox,'String',actionsListBoxStr);
%                     else
%                         set(handles.actionsListbox,'String','');
%                         set(handles.actionsListbox,'Value',1);
%                     end
%                 end
%             end
        end

        % Button pushed function: addActionButton
        function addActionButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addActionButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            event = getappdata(handles.lvd_editEventGUI,'event');
            lv = event.script.lvdData.launchVehicle;
            actionStrs = EventActionEnum.getActionTypeNameStrs();

            out = AppDesignerGUIOutput({[],false});
            listdlgARH_App('ListString',actionStrs, ...
                            'Name','Select Action', ...
                            'PromptString',{'Select the action you wish to add','to this event:'}, ...
                            'SelectionMode','single', ...
                            'ListSize',[210 300], ...
                            'out',out);
            dialogInd = out.output{1};
            tf = out.output{2};

            if(tf == 1)
                [m,~] = enumeration('EventActionEnum');
                inds = strcmpi({m.nameStr},actionStrs{dialogInd});

                actionEnum = m(inds);

                newAction = feval(actionEnum.classNameStr);
                newAction.event = event;
                addActionTf = newAction.openEditActionUI(newAction, lv);

                if(addActionTf)
                    event.addAction(newAction);
                end

                [actionsListboxStr, actions] = event.getActionsListboxStr();
                app.actionsListbox.Items = actionsListboxStr;
                app.actionsListbox.ItemsData = actions;

                if(handles.actionsListbox.Value <= 0)
                    handles.actionsListbox.Value = 1;
                elseif(handles.actionsListbox.Value > length(handles.actionsListbox.String))
                    handles.actionsListbox.Value = length(handles.actionsListbox.String);
                end
            end
        end

        % Menu selected function: determineFastestIntegratorMenu
        function determineFastestIntegratorMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to determineFastestIntegratorMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            event = getappdata(handles.lvd_editEventGUI,'event');
            lvdData = event.lvdData;
            script = lvdData.script;

            initStateLogEntry = lvdData.stateLog.getFirstStateLogForEvent(event);

            simDriver = script.simDriver;
            tStartSimTime = initStateLogEntry.time;
            isSparseOutput = false;

            activeNonSeqEvts = script.nonSeqEvts.getNonSeqEventsForScriptEvent(event);
            for(j=1:length(activeNonSeqEvts))
                activeNonSeqEvts(j).initEvent(initStateLogEntry);
            end

            oldIntegrator = event.integratorObj;

%             hWaitBar = waitbar(0, '');
            hWaitBar = uiprogressdlg(app.lvd_editEventGUI, "Title","Determine Fastest Integrator");

            try
                [m,~] = enumeration('IntegratorEnum');
                m = m([m.isFirstOrder]);

                results = cell(length(m),2);
                for(i=1:length(m))
                    newIntegratorEnum = m(i);

                    if(isvalid(hWaitBar))
                        progVal = ((i-1)/length(m));
                        msgStr = sprintf('Running event with integrator "%s"...',newIntegratorEnum.nameStr);
%                         waitbar(progVal, hWaitBar, msgStr);
                        hWaitBar.Value = progVal;
                        hWaitBar.Message = msgStr;
                    end

                    tStartPropTime = tic();

                    integratorObj = IntegratorEnum.getIntegratorObjFromEnum(newIntegratorEnum);
                    event.integratorObj = integratorObj;

                    tt = tic;
                    event.executeEvent(initStateLogEntry, simDriver, tStartPropTime, tStartSimTime, isSparseOutput, activeNonSeqEvts);
                    elapsedTime = toc(tt);

                    results(i,:) = {newIntegratorEnum, elapsedTime};
                end
            catch ME
                event.integratorObj = oldIntegrator;
                throw(ME);
            end

            if(isvalid(hWaitBar))
                close(hWaitBar);
            end

            msg = {'Results of integrator speed test:', ''};
            for(i=1:size(results,1))
                integrator = results{i,1};
                time = results{i,2};

                msg = horzcat(msg, sprintf('%s:     %.3f sec', integrator.nameStr, time)); %#ok<AGROW>
            end

            [~,I] = min([results{:,2}]);
            fastestIntegratorEnum = m(I);

            msg = horzcat(msg,{'',sprintf('Apply fastest integrator (%s) for this event?',fastestIntegratorEnum.nameStr)});

            button = questdlg(msg,'Speed Test Results','Yes','No','Yes');

            if(strcmpi(button,'Yes'))
                fastestIntegratorObj = IntegratorEnum.getIntegratorObjFromEnum(fastestIntegratorEnum);
                event.integratorObj = fastestIntegratorObj;

                ind = IntegratorEnum.getIndOfListboxStr(fastestIntegratorEnum.nameStr);
                handles.integratorCombo.Value = ind;
                
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                integratorCombo_Callback(app, evt);
            else
                event.integratorObj = oldIntegrator;
            end
        end

        % Button pushed function: editTermCondParamsButton
        function editTermCondParamsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editTermCondParamsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            event = getappdata(handles.lvd_editEventGUI,'event');
            app.editSelectedTermCond(event);

        end

        % Value changed function: integratorCombo
        function integratorCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.integratorCombo;
            
            % hObject    handle to integratorCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns integratorCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from integratorCombo
            contents = cellstr(get(hObject,'String'));

            if(get(hObject,'Value') < 1 || get(hObject,'Value') > length(contents))
                nameStr = contents{1};
            else
                nameStr = contents{get(hObject,'Value')};
            end

            [~,m] = IntegratorEnum.getIndOfListboxStr(nameStr);
            handles.integratorDetailLabel.String = m.descStr;
        end

        % Button pushed function: integratorOptionsButton
        function integratorOptionsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to integratorOptionsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            integratorObj = getSelectedEventIntegratorObj(app, handles);

            options = integratorObj.getOptions();
            options.openOptionsDialog();
        end

        % Window key press function: lvd_editEventGUI
        function lvd_editEventGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_editEventGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            %Enter inside the notes box adds a line; it must not save the event.
            if(any(strcmp(eventdata.Key, {'return', 'enter'})) && LvdMarkdownNotesEditor.isEditorFocused(app.lvd_editEventGUI, app.notesTextArea))
                return;
            end

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_editEventGUI);
            end
        end

        % Button pushed function: nextEventButton
        function nextEventButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to nextEventButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            event = getappdata(handles.lvd_editEventGUI,'event');
            eventNum = event.getEventNum();

            script = event.script;
            numEvents = script.getTotalNumOfEvents();

            if(numEvents > 1)
                if(eventNum >= numEvents)
                    newEvent = script.getEventForInd(1);
                else
                    newEvent = script.getEventForInd(eventNum + 1);
                end

                loadNewEvent(app, newEvent, handles);
            end
        end

        % Menu selected function: optimizeInitialStepSizeMenu
        function optimizeInitialStepSizeMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to optimizeInitialStepSizeMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            event = getappdata(handles.lvd_editEventGUI,'event');
            lvdData = event.lvdData;
            script = lvdData.script;

            simDriver = script.simDriver;
            tStartSimTime = 0;
            isSparseOutput = false;

            initStateLogEntry = lvdData.stateLog.getFirstStateLogForEvent(event);

            activeNonSeqEvts = script.nonSeqEvts.getNonSeqEventsForScriptEvent(event);
            for(j=1:length(activeNonSeqEvts)) %#ok<*NO4LP> 
                activeNonSeqEvts(j).initEvent(initStateLogEntry);
            end

            oldInitStep = event.initialStep;

            fun = @(x) optInitStepSizeObjFcn(app, x, event, initStateLogEntry, simDriver, tStartSimTime, isSparseOutput, activeNonSeqEvts);
            x0 = oldInitStep;
            lb = 0.01;
            ub = 1E10;
            %     options = optimoptions(@fmincon, 'Display','iter', 'OptimalityTolerance',1E-2, 'StepTolerance',1E-10, 'PlotFcn',{@optimplotx, @optimplotfval});
            options = optimoptions(@patternsearch, 'Display','iter', 'UseParallel',lvdData.optimizer.usesParallel(), 'PlotFcn',{@psplotbestx, @psplotbestf});

            %     x = fmincon(fun,x0,[],[],[],[],lb,ub,[],options);
            x = patternsearch(fun,x0,[],[],[],[],lb,ub,[],options);
            msg = sprintf('Results of initial step size tuning: %0.3f sec', x);

            msg = horzcat(msg,{'','',sprintf('Apply optimized initial step size to this event?')});

            button = questdlg(msg,'Speed Test Results','Yes','No','Yes');

            if(strcmpi(button,'Yes'))
                handles.initialStepText.String = fullAccNum2Str(x);
                initialStepText_Callback(handles.initialStepText, [], handles);
            else
                integrator = event.integratorObj;
                propOptions = integrator.getOptions();
                propOptions.setIntegratorStepSize(oldInitStep);
            end
        end

        % Button pushed function: prevEventButton
        function prevEventButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to prevEventButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            event = getappdata(handles.lvd_editEventGUI,'event');
            eventNum = event.getEventNum();

            script = event.script;
            numEvents = script.getTotalNumOfEvents();

            if(numEvents > 1)
                if(eventNum <= 1)
                    newEvent = script.getEventForInd(numEvents);
                else
                    newEvent = script.getEventForInd(eventNum - 1);
                end

                loadNewEvent(app, newEvent, handles);
            end
        end

        % Value changed function: propagatorCombo
        function propagatorCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.propagatorCombo;
            
            % hObject    handle to propagatorCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns propagatorCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from propagatorCombo
            contents = cellstr(get(hObject,'String'));

            if(get(hObject,'Value') < 1 || get(hObject,'Value') > length(contents))
                nameStr = contents{1};
            else
                nameStr = contents{get(hObject,'Value')};
            end

            [~,m] = PropagatorEnum.getIndForName(nameStr);
            handles.propagatorDetailLabel.String = m.desc;

            %Handle changing available integrators
            integratorObj = app.getSelectedEventIntegratorObj(handles);
            [intListboxStr, m] = IntegratorEnum.getListBoxStrs(integratorObj.integratorEnum.isFirstOrder, integratorObj.integratorEnum.isSecondOrder);

            app.integratorCombo.Items = string(intListboxStr);
            if(any(integratorObj.integratorEnum == m))
                handles.integratorCombo.Value = find(integratorObj.integratorEnum == m, 1);
            end

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            integratorCombo_Callback(app, evt);
        end

        % Button pushed function: propagatorOptionsButton
        function propagatorOptionsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to propagatorOptionsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            propagatorObj = getSelectedEventPropagatorObj(app, handles);

            propagatorObj.openOptionsDialog();
        end

        % Button pushed function: removeActionButton
        function removeActionButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeActionButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            event = getappdata(handles.lvd_editEventGUI,'event');

%             selActionInd = get(handles.actionsListbox,'Value');
            action = app.actionsListbox.Value;
            if(not(isempty(action)))
                event.removeAction(action);
    
    %             set(handles.actionsListbox,'String',event.getActionsListboxStr());
                [actionsListboxStr, actions] = event.getActionsListboxStr();
                app.actionsListbox.Items = actionsListboxStr;
                app.actionsListbox.ItemsData = actions;
    
%                 aListBosStr = get(handles.actionsListbox,'String');
%                 if(iscell(aListBosStr) && length(aListBosStr)<selActionInd)
%                     set(handles.actionsListbox,'Value',length(aListBosStr));
%                 end
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
%             hObject = handles.saveAndCloseButton;
            
            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                saveEvent(app, handles);
            
                app.output.output = {true};
                close(app.lvd_editEventGUI);
            else
                msgbox(errMsg,'Errors were found while editing the event.','error');
            end
        end

        % Window button down function: lvd_editEventGUI
        function lvd_editEventGUIWindowButtonDown(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            selType = app.lvd_editEventGUI.SelectionType;
            curObj = app.lvd_editEventGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.actionsListbox)
                    event = getappdata(handles.lvd_editEventGUI,'event');
                    lv = event.script.lvdData.launchVehicle;
    
                    if(not(isempty(event.getActionsListboxStr())))
                        action = app.actionsListbox.Value;
    
                        if(not(isempty(action)))
                            action.event = event;
                            action.openEditActionUI(action, lv);
        
                            [actionsListBoxStr, actions] = event.getActionsListboxStr();
                            
                            if(not(isempty(actionsListBoxStr)))
                                app.actionsListbox.Items = actionsListBoxStr;
                                app.actionsListbox.ItemsData = actions;
                            else
                                app.actionsListbox.Items = {};
                                app.actionsListbox.ItemsData = AbstractEventAction.empty(1,0);
                            end
                        end
                    end
                end
            end
        end

        % Value changed function: termCondListbox
        function termCondListbox_Callback(app, event)
            evt = getappdata(app.lvd_editEventGUI, 'event');
            app.refreshTermCondList(evt, app.getSelectedTermCondInd());
        end

        % Button pushed function: addTermCondButton
        function addTermCondButton_Callback(app, event)
            evt = getappdata(app.lvd_editEventGUI, 'event');
            evt.addTermCond(EventDurationTermCondition(0), EventTermCondDirectionEnum.NoDir);
            app.refreshTermCondList(evt, evt.getNumTermConds());

            %open the editor so the new condition can be set up right away
            app.editSelectedTermCond(evt);
        end

        % Button pushed function: removeTermCondButton
        function removeTermCondButton_Callback(app, event)
            evt = getappdata(app.lvd_editEventGUI, 'event');

            if(evt.getNumTermConds() > 1)
                ind = app.getSelectedTermCondInd();
                evt.removeTermCondByInd(ind);
                app.refreshTermCondList(evt, min(ind, evt.getNumTermConds()));
            end
        end

        % Value changed function: eventTermCondDirCombo
        function eventTermCondDirCombo_Callback(app, event)
            evt = getappdata(app.lvd_editEventGUI, 'event');
            ind = app.getSelectedTermCondInd();

            [dirEnum, ~] = EventTermCondDirectionEnum.getEnumForListboxStr(app.eventTermCondDirCombo.Value);
            evt.setTermCondDirByInd(ind, dirEnum);

            app.refreshTermCondList(evt, ind);
        end

        % Value changed function: nonSeqEnabledCheckbox, 
        % ...and 2 other components
        function advancedCheckbox_Callback(app, event)
            app.updateAdvancedEnableStates();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_editEventGUI = uifigure('Visible', 'off');
            app.lvd_editEventGUI.Name = 'Edit Event';
            app.lvd_editEventGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_editEventGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_editEventGUIWindowButtonDown, true);
            app.lvd_editEventGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_editEventGUI_WindowKeyPressFcn, true);
            app.lvd_editEventGUI.Tag = 'lvd_editEventGUI';
            app.GridLayout = uigridlayout(app.lvd_editEventGUI);
            app.EventTabGroup = uitabgroup(app.GridLayout);
            app.EventTab = uitab(app.EventTabGroup);
            app.EventTab.Title = 'Event';
            app.EventTabGrid = uigridlayout(app.EventTab);
            app.Panel = uipanel(app.EventTabGrid);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.EventSpecificPropagatorSettingsPanel = uipanel(app.GridLayout7);
            app.EventSpecificPropagatorSettingsPanel.Title = 'Event-Specific Propagator Settings';
            app.GridLayout10 = uigridlayout(app.EventSpecificPropagatorSettingsPanel);
            app.GridLayout12 = uigridlayout(app.GridLayout10);
            app.integratorCombo = uidropdown(app.GridLayout12);
            app.integratorCombo.Items = {'Pop-up Menu'};
            app.integratorCombo.ValueChangedFcn = createCallbackFcn(app, @integratorCombo_Callback, true);
            app.integratorCombo.Tag = 'integratorCombo';
            app.integratorCombo.Tooltip = 'Select the integrator algorithm that should be used to propagate this event.';
            app.integratorCombo.Value = 'Pop-up Menu';
            app.integratorOptionsButton = uibutton(app.GridLayout12, 'push');
            app.integratorOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @integratorOptionsButton_Callback, true);
            app.integratorOptionsButton.Tag = 'integratorOptionsButton';
            app.integratorOptionsButton.Tooltip = 'Sets the options for the selected integrator.';
            app.integratorOptionsButton.Text = 'Integrator Options';
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.propagatorCombo = uidropdown(app.GridLayout11);
            app.propagatorCombo.Items = {'Pop-up Menu'};
            app.propagatorCombo.ValueChangedFcn = createCallbackFcn(app, @propagatorCombo_Callback, true);
            app.propagatorCombo.Tag = 'propagatorCombo';
            app.propagatorCombo.Tooltip = 'Select the propagator that should be used to propagate this event.  This option dictates the type and complexity of the motion.  Use "force model" if unsure.';
            app.propagatorCombo.Value = 'Pop-up Menu';
            app.propagatorOptionsButton = uibutton(app.GridLayout11, 'push');
            app.propagatorOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @propagatorOptionsButton_Callback, true);
            app.propagatorOptionsButton.Tag = 'propagatorOptionsButton';
            app.propagatorOptionsButton.Tooltip = 'Sets the options for the selected propagator.';
            app.propagatorOptionsButton.Text = 'Propagator Options';
            app.propagatorDetailLabel = uilabel(app.GridLayout10);
            app.propagatorDetailLabel.Tag = 'propagatorDetailLabel';
            app.propagatorDetailLabel.Text = 'Static Text';
            app.integratorDetailLabel = uilabel(app.GridLayout10);
            app.integratorDetailLabel.Tag = 'integratorDetailLabel';
            app.integratorDetailLabel.Text = 'Static Text';
            app.propDirCombo = uidropdown(app.GridLayout10);
            app.propDirCombo.Items = {'Pop-up Menu'};
            app.propDirCombo.Tag = 'propDirCombo';
            app.propDirCombo.Value = 'Pop-up Menu';
            app.checkSoITransCheckbox = uicheckbox(app.GridLayout10);
            app.checkSoITransCheckbox.Tag = 'checkSoITransCheckbox';
            app.checkSoITransCheckbox.Tooltip = 'When checked, the propagator will detect and handle SoI transitions.  When not checked, SoI transitions are ignored unless the termination condition is Next SoI Transition.  Uncheck to improve integrator performance.';
            app.checkSoITransCheckbox.Text = 'Consider SoI Transitions';
            app.ActionsPanel = uipanel(app.GridLayout7);
            app.ActionsPanel.Title = 'Actions';
            app.GridLayout8 = uigridlayout(app.ActionsPanel);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.removeActionButton = uibutton(app.GridLayout9, 'push');
            app.removeActionButton.ButtonPushedFcn = createCallbackFcn(app, @removeActionButton_Callback, true);
            app.removeActionButton.Tag = 'removeActionButton';
            app.removeActionButton.Icon = 'remove.png';
            app.removeActionButton.Text = 'Remove Action';
            app.addActionButton = uibutton(app.GridLayout9, 'push');
            app.addActionButton.ButtonPushedFcn = createCallbackFcn(app, @addActionButton_Callback, true);
            app.addActionButton.Tag = 'addActionButton';
            app.addActionButton.Icon = 'add.png';
            app.addActionButton.Text = 'Add Action';
            app.actionExecNodeCombo = uidropdown(app.GridLayout8);
            app.actionExecNodeCombo.Items = {'Pop-up Menu'};
            app.actionExecNodeCombo.Tag = 'actionExecNodeCombo';
            app.actionExecNodeCombo.Tooltip = 'The selection here dictates when the above actions will be executed: either after the propagationu completes (the default) or before the propagation starts.';
            app.actionExecNodeCombo.Value = 'Pop-up Menu';
            app.actionsListbox = uilistbox(app.GridLayout8);
            app.actionsListbox.Items = {'Listbox'};
            app.actionsListbox.ValueChangedFcn = createCallbackFcn(app, @actionsListbox_Callback, true);
            app.actionsListbox.Tag = 'actionsListbox';
            app.actionsListbox.Tooltip = 'Actions associated with this event.  Actions are operations carried out at the end of an event.';
            app.actionsListbox.Value = 'Listbox';
            app.TerminationConditionPanel = uipanel(app.GridLayout3);
            app.TerminationConditionPanel.Title = 'Termination Condition';
            app.GridLayout6 = uigridlayout(app.TerminationConditionPanel);
            app.TermCondLogicGrid = uigridlayout(app.GridLayout6);
            app.termCondLogicCombo = uidropdown(app.TermCondLogicGrid);
            app.termCondLogicCombo.Items = {'Any'};
            app.termCondLogicCombo.Tooltip = 'Any: the event ends the first time one of its conditions fires.  All: every condition must fire at least once (conditions latch as they fire).  Non-sequential events always use Any.';
            app.termCondLogicCombo.Value = 'Any';
            app.termCondLogicLabel = uilabel(app.TermCondLogicGrid);
            app.termCondLogicLabel.Text = 'Condition Logic';
            app.TermCondDirGrid = uigridlayout(app.GridLayout6);
            app.termCondDirLabel = uilabel(app.TermCondDirGrid);
            app.termCondDirLabel.Text = 'Crossing Direction';
            app.eventTermCondDirCombo = uidropdown(app.TermCondDirGrid);
            app.eventTermCondDirCombo.Items = {'Pop-up Menu'};
            app.eventTermCondDirCombo.ValueChangedFcn = createCallbackFcn(app, @eventTermCondDirCombo_Callback, true);
            app.eventTermCondDirCombo.Tag = 'eventTermCondDirCombo';
            app.eventTermCondDirCombo.Tooltip = 'The direction the value of the termination condition must be moving in order for this condition to trigger.  Leave at "Either Increasing or Decreasing" to use either direction.';
            app.eventTermCondDirCombo.Value = 'Pop-up Menu';
            app.TermCondButtonGrid = uigridlayout(app.GridLayout6);
            app.removeTermCondButton = uibutton(app.TermCondButtonGrid, 'push');
            app.removeTermCondButton.ButtonPushedFcn = createCallbackFcn(app, @removeTermCondButton_Callback, true);
            app.removeTermCondButton.Icon = 'remove.png';
            app.removeTermCondButton.Tooltip = 'Remove the selected termination condition.  An event always keeps at least one.';
            app.removeTermCondButton.Text = 'Remove';
            app.addTermCondButton = uibutton(app.TermCondButtonGrid, 'push');
            app.addTermCondButton.ButtonPushedFcn = createCallbackFcn(app, @addTermCondButton_Callback, true);
            app.addTermCondButton.Icon = 'add.png';
            app.addTermCondButton.Tooltip = 'Add a termination condition to this event and open its editor.';
            app.addTermCondButton.Text = 'Add';
            app.editTermCondParamsButton = uibutton(app.TermCondButtonGrid, 'push');
            app.editTermCondParamsButton.ButtonPushedFcn = createCallbackFcn(app, @editTermCondParamsButton_Callback, true);
            app.editTermCondParamsButton.Tag = 'editTermCondParamsButton';
            app.editTermCondParamsButton.Tooltip = 'Edit the selected termination condition''s type and parameters.';
            app.editTermCondParamsButton.Text = 'Edit...';
            app.termCondListbox = uilistbox(app.GridLayout6);
            app.termCondListbox.Items = {};
            app.termCondListbox.ValueChangedFcn = createCallbackFcn(app, @termCondListbox_Callback, true);
            app.termCondListbox.Tooltip = 'The termination conditions of this event, in order.  Under "Any" logic the first condition to cross ends the event; under "All" logic every condition must cross at least once.  Select a condition to see and set its crossing direction.';
            app.termCondListbox.Value = {};
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.GridLayout15 = uigridlayout(app.GridLayout4);
            app.Image4 = uiimage(app.GridLayout15);
            app.Image4.ImageSource = 'settings.png';
            app.plotMethodCombo = uidropdown(app.GridLayout15);
            app.plotMethodCombo.Items = {'Pop-up Menu'};
            app.plotMethodCombo.Tag = 'plotMethodCombo';
            app.plotMethodCombo.Tooltip = 'Plot continuous will connect the final state of the previous event with the initials state of this event.  Skipping the first state will effectively only plot this event and could be discontinuous with the previous event.';
            app.plotMethodCombo.Value = 'Pop-up Menu';
            app.GridLayout14 = uigridlayout(app.GridLayout4);
            app.Image = uiimage(app.GridLayout14);
            app.Image.Tooltip = {'Color Options'};
            app.Image.ImageSource = 'color_wheel.png';
            app.colorSpecCombo = uidropdown(app.GridLayout14);
            app.colorSpecCombo.Items = {'Pop-up Menu'};
            app.colorSpecCombo.Tag = 'colorSpecCombo';
            app.colorSpecCombo.Value = 'Pop-up Menu';
            app.GridLayout13 = uigridlayout(app.GridLayout4);
            app.Image3 = uiimage(app.GridLayout13);
            app.Image3.Tooltip = {'Marker Options'};
            app.Image3.ImageSource = 'diamond.png';
            app.markerSizeCombo = uidropdown(app.GridLayout13);
            app.markerSizeCombo.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.markerSizeCombo.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.markerSizeCombo.Value = '6';
            app.text9_2 = uilabel(app.GridLayout13);
            app.text9_2.Tag = 'text9';
            app.text9_2.Text = 'Size';
            app.markerTypeCombo = uidropdown(app.GridLayout13);
            app.markerTypeCombo.Tooltip = {'Places a marker of the specified shape and size at the various points at which the integrator returns data when propagating this event.  Color is the same as the line color. '};
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.Image2 = uiimage(app.GridLayout5);
            app.Image2.Tooltip = {'Line Options'};
            app.Image2.ImageSource = 'line.png';
            app.lineWidthCombo = uidropdown(app.GridLayout5);
            app.lineWidthCombo.Items = {'0.25', '0.50', '0.75', '1.0', '1.5', '2.0', '2.5', '3.0', '3.5', '4.0', '4.5', '5.0'};
            app.lineWidthCombo.Tag = 'lineWidthCombo';
            app.lineWidthCombo.Value = '1.5';
            app.text9 = uilabel(app.GridLayout5);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Width';
            app.lineSpecCombo = uidropdown(app.GridLayout5);
            app.lineSpecCombo.Items = {'Pop-up Menu'};
            app.lineSpecCombo.Tag = 'lineSpecCombo';
            app.lineSpecCombo.Value = 'Pop-up Menu';
            app.eventNameText = uieditfield(app.GridLayout4, 'text');
            app.eventNameText.Tag = 'eventNameText';
            app.eventNameText.Tooltip = 'The name of this event as it appears in the mission script.';
            app.eventNameText.Value = 'Untitled Event';
            app.text3 = uilabel(app.GridLayout4);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Event Name';
            app.AdvancedTab = uitab(app.EventTabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.AdvancedTabGrid = uigridlayout(app.AdvancedTab);
            app.NonSeqPanel = uipanel(app.AdvancedTabGrid);
            app.NonSeqPanel.Title = 'Non-Sequential Event';
            app.NonSeqGrid = uigridlayout(app.NonSeqPanel);
            app.nonSeqLogExecCheckbox = uicheckbox(app.NonSeqGrid);
            app.nonSeqLogExecCheckbox.Tooltip = 'Write the state produced by this event''s actions into the state log as its own entry, so the discontinuity is visible in plots and exports.';
            app.nonSeqLogExecCheckbox.Text = 'Log executions in the state log';
            app.nonSeqPriorityField = uieditfield(app.NonSeqGrid, 'numeric');
            app.nonSeqPriorityField.Tooltip = 'When more than one non-sequential event is armed on the same integration step, the highest priority fires first.';
            app.priorityLabel = uilabel(app.NonSeqGrid);
            app.priorityLabel.Text = 'Priority';
            app.nonSeqEnabledCheckbox = uicheckbox(app.NonSeqGrid);
            app.nonSeqEnabledCheckbox.ValueChangedFcn = createCallbackFcn(app, @advancedCheckbox_Callback, true);
            app.nonSeqEnabledCheckbox.Tooltip = 'Arm this non-sequential event.  Switching it off leaves it and its execution count in the mission but stops it from firing.';
            app.nonSeqEnabledCheckbox.Text = 'Enabled';
            app.nonSeqEnabledCheckbox.Value = true;
            app.EventLimitsPanel = uipanel(app.AdvancedTabGrid);
            app.EventLimitsPanel.Title = 'Event Limit Overrides';
            app.EventLimitsGrid = uigridlayout(app.EventLimitsPanel);
            app.maxDurUnitLabel = uilabel(app.EventLimitsGrid);
            app.maxDurUnitLabel.Text = 'sec';
            app.maxDurField = uieditfield(app.EventLimitsGrid, 'numeric');
            app.useMaxDurCheckbox = uicheckbox(app.EventLimitsGrid);
            app.useMaxDurCheckbox.ValueChangedFcn = createCallbackFcn(app, @advancedCheckbox_Callback, true);
            app.useMaxDurCheckbox.Tooltip = 'Stop this event after the given duration, measured from the start of the event, regardless of its termination conditions.  Only ever tightens the mission-wide limit.';
            app.useMaxDurCheckbox.Text = 'Override maximum duration';
            app.terrainRelCheckbox = uicheckbox(app.EventLimitsGrid);
            app.terrainRelCheckbox.Tooltip = 'Measure the minimum altitude against the terrain under the vehicle instead of the body''s mean radius.';
            app.terrainRelCheckbox.Text = 'Minimum altitude is relative to the terrain';
            app.minAltUnitLabel = uilabel(app.EventLimitsGrid);
            app.minAltUnitLabel.Text = 'km';
            app.minAltField = uieditfield(app.EventLimitsGrid, 'numeric');
            app.useMinAltCheckbox = uicheckbox(app.EventLimitsGrid);
            app.useMinAltCheckbox.ValueChangedFcn = createCallbackFcn(app, @advancedCheckbox_Callback, true);
            app.useMinAltCheckbox.Tooltip = 'Replace the mission-wide minimum altitude (Settings) for this event only.';
            app.useMinAltCheckbox.Text = 'Override minimum altitude';
            app.GroupNotesPanel = uipanel(app.AdvancedTabGrid);
            app.GroupNotesPanel.Title = 'Group and Notes';
            app.GroupNotesGrid = uigridlayout(app.GroupNotesPanel);
            app.eventTagsText = uieditfield(app.GroupNotesGrid, 'text');
            app.eventTagsText.Tag = 'eventTagsText';
            app.eventTagsText.Tooltip = 'Free-text tags for this event. The main script search matches these tags.';
            app.tagsLabel = uilabel(app.GroupNotesGrid);
            app.tagsLabel.Tag = 'tagsLabel';
            app.tagsLabel.Text = 'Tags';
            app.notesTextArea = uitextarea(app.GroupNotesGrid);
            app.notesTextArea.Tooltip = 'Free-form notes about this event.  Events with notes are marked in the script list.';
            app.notesLabel = uilabel(app.GroupNotesGrid);
            app.notesLabel.Text = 'Notes';
            app.groupNameCombo = uidropdown(app.GroupNotesGrid);
            app.groupNameCombo.Items = {'<No Group>'};
            app.groupNameCombo.Editable = 'on';
            app.groupNameCombo.Tooltip = 'Events sharing a group name are shown together in the script list, where the group can be collapsed to a single row.  Pick an existing group or type a new name; <No Group> removes the event from any group.';
            app.groupNameCombo.Value = '<No Group>';
            app.groupNameLabel = uilabel(app.GroupNotesGrid);
            app.groupNameLabel.Text = 'Group Name';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.nextEventButton = uibutton(app.GridLayout2, 'push');
            app.nextEventButton.ButtonPushedFcn = createCallbackFcn(app, @nextEventButton_Callback, true);
            app.nextEventButton.Tag = 'nextEventButton';
            app.nextEventButton.Icon = 'right-arrow.png';
            app.nextEventButton.Tooltip = 'Save current event data and jump to next event in the script.';
            app.nextEventButton.Text = '';
            app.prevEventButton = uibutton(app.GridLayout2, 'push');
            app.prevEventButton.ButtonPushedFcn = createCallbackFcn(app, @prevEventButton_Callback, true);
            app.prevEventButton.Tag = 'prevEventButton';
            app.prevEventButton.Icon = 'left-arrow.png';
            app.prevEventButton.Tooltip = 'Save current event data and jump to previous event in the script.';
            app.prevEventButton.Text = '';
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Event';
            app.integratorContextMenu = uicontextmenu(app.lvd_editEventGUI);
            app.integratorContextMenu.Tag = 'integratorContextMenu';
            app.determineFastestIntegratorMenu = uimenu(app.integratorContextMenu);
            app.determineFastestIntegratorMenu.MenuSelectedFcn = createCallbackFcn(app, @determineFastestIntegratorMenu_Callback, true);
            app.determineFastestIntegratorMenu.Text = 'Determine Fastest Integrator';
            app.determineFastestIntegratorMenu.Tag = 'determineFastestIntegratorMenu';
            % Assign app.integratorContextMenu
            app.integratorCombo.ContextMenu = app.integratorContextMenu;
            app.initialStepSizeContextMenu = uicontextmenu(app.lvd_editEventGUI);
            app.initialStepSizeContextMenu.Tag = 'initialStepSizeContextMenu';
            app.optimizeInitialStepSizeMenu = uimenu(app.initialStepSizeContextMenu);
            app.optimizeInitialStepSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @optimizeInitialStepSizeMenu_Callback, true);
            app.optimizeInitialStepSizeMenu.Text = 'Optimize Initial Step Size';
            app.optimizeInitialStepSizeMenu.Tag = 'optimizeInitialStepSizeMenu';
            % Show the figure after all components are created
            app.lvd_editEventGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_editEventGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_editEventGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_editEventGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_editEventGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_editEventGUI)
        end
    end
end