classdef lvd_editEvtTermCond_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_editEvtTermCond    matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        Panel                  matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        ReferenceFramePanel    matlab.ui.container.Panel
        GridLayout4            matlab.ui.container.GridLayout
        GridLayout5            matlab.ui.container.GridLayout
        setFrameOptionsButton  matlab.ui.control.Button
        refFrameTypeCombo      matlab.ui.control.DropDown
        text10                 matlab.ui.control.Label
        refFrameNameLabel      matlab.ui.control.Label
        ParametersPanel        matlab.ui.container.Panel
        GridLayout6            matlab.ui.container.GridLayout
        GridLayout8            matlab.ui.container.GridLayout
        refStopwatchCombo      matlab.ui.control.DropDown
        refEngineCombo         matlab.ui.control.DropDown
        refTankCombo           matlab.ui.control.DropDown
        refStageCombo          matlab.ui.control.DropDown
        text9                  matlab.ui.control.Label
        text8                  matlab.ui.control.Label
        text7                  matlab.ui.control.Label
        text6                  matlab.ui.control.Label
        GridLayout7            matlab.ui.container.GridLayout
        paramUbText            matlab.ui.control.EditField
        paramLbText            matlab.ui.control.EditField
        optParamCheckbox       matlab.ui.control.CheckBox
        numParamUnitLabel      matlab.ui.control.Label
        numParamText           matlab.ui.control.EditField
        numParamLabel          matlab.ui.control.Label
        termCondTypeCombo      matlab.ui.control.DropDown
        GridLayout2            matlab.ui.container.GridLayout
        cancelButton           matlab.ui.control.Button
        saveAndCloseButton     matlab.ui.control.Button
        text2                  matlab.ui.control.Label
    end


    methods (Access = private)
        function params = getParamsForSelectedTermCondType(app, handles)
            event = getappdata(handles.lvd_editEvtTermCond,'event');
            lv = event.script.lvdData.launchVehicle;

            [~,stages] = lv.getStagesListBoxStr();
            [~,tanks] = lv.getTanksListBoxStr();
            [~,engines] = lv.getEnginesListBoxStr();
            [~, stopwatches] = lv.getStopwatchesListBoxStr();

            stage = stages(1);
            tank = tanks(1);
            engine = engines(1);

            if(not(isempty(stopwatches)))
                stopwatch = stopwatches(1);
            else
                stopwatch = LaunchVehicleStopwatch.empty(1,0);
            end

            [m,~] = enumeration('TerminationConditionEnum');
            contents = cellstr(get(handles.termCondTypeCombo,'String'));
            termCondType = contents{get(handles.termCondTypeCombo,'Value')};
            ind = strcmpi({m.nameStr},termCondType);
            termCond = feval(sprintf('%s.getTermCondForParams',m(ind).classNameStr), 0, stage, tank, engine, stopwatch);

            params = termCond.getTermCondUiStruct();
        end

        function bodyInfo = getSelectedBodyInfo(app, handles)
            curFrame = getappdata(handles.lvd_editEvtTermCond,'frame');
            bodyInfo = curFrame.getOriginBody();
        end

        function populateGUI(app, handles, event)
            termCondStrs = TerminationConditionEnum.getTermCondTypeNameStrs();
            set(handles.termCondTypeCombo,'String',termCondStrs);

            termCond = event.termCond;
            ind = TerminationConditionEnum.getIndOfListboxStrsForTermCond(termCond);
            set(handles.termCondTypeCombo,'Value',ind);

            params = termCond.getTermCondUiStruct();

            value = params.value;
            if(isempty(value))
                value = 0.0;
            end

            if(strcmpi(params.paramUnit,'deg'))
                value = rad2deg(value);
            elseif(strcmpi(params.paramUnit,'%'))
                value = value*100;
            end

            set(handles.numParamText,'String',fullAccNum2Str(value));

            lv = event.script.lvdData.launchVehicle;

            stagesListStr = lv.getStagesListBoxStr();
            set(handles.refStageCombo,'String',stagesListStr);

            tanksListStr = lv.getTanksListBoxStr();
            set(handles.refTankCombo,'String',tanksListStr);

            enginesListStr = lv.getEnginesListBoxStr();
            set(handles.refEngineCombo,'String',enginesListStr);

            swListStr = lv.getStopwatchesListBoxStr();
            set(handles.refStopwatchCombo,'String',swListStr);
            if(isempty(swListStr))
                set(handles.refStopwatchCombo,'String','<No Stopwatch Yet Created>');
                set(handles.refStopwatchCombo,'Value',1);
            end

            set(handles.numParamLabel,'String',params.paramName);
            set(handles.numParamUnitLabel,'String',params.paramUnit);

            set(handles.numParamText,'Enable',params.useParam);
            set(handles.optParamCheckbox,'Enable',params.useParam);
            set(handles.paramLbText,'Enable',params.useParam);
            set(handles.paramUbText,'Enable',params.useParam);

            set(handles.refStageCombo,'Enable',params.useStages);
            set(handles.refTankCombo,'Enable',params.useTanks);
            set(handles.refEngineCombo,'Enable',params.useEngines);
            set(handles.refStopwatchCombo,'Enable',params.useStopwatches);

            if(not(isempty(params.refStage)))
                set(handles.refStageCombo,'Value',lv.getListBoxIndForStage(params.refStage));
            end

            if(not(isempty(params.refTank)))
                set(handles.refTankCombo,'Value',lv.getListBoxIndForTank(params.refTank));
            end

            if(not(isempty(params.refEngine)))
                set(handles.refEngineCombo,'Value',lv.getListBoxIndForEngine(params.refEngine));
            end

            if(not(isempty(params.refStopwatch)))
                set(handles.refStopwatchCombo,'Value',lv.getListBoxIndForStopwatch(params.refStopwatch));
            end

            optVar = termCond.getExistingOptVar();
            if(isempty(optVar))
                useTf = false([1,1]);
                lb = zeros(size(useTf));
                ub = zeros(size(useTf));
            else
                useTf = optVar.getUseTfForVariable();
                optVar.setUseTfForVariable(true(size(useTf)));
                [lb, ub] = optVar.getBndsForVariable();
                optVar.setUseTfForVariable(useTf);
            end

            if(strcmpi(params.paramUnit,'deg'))
                lb = rad2deg(lb);
                ub = rad2deg(ub);
            elseif(strcmpi(params.paramUnit,'%'))
                lb = lb*100;
                ub = ub*100;
            end

            lbStr = fullAccNum2Str(lb);
            ubStr = fullAccNum2Str(ub);

            set(handles.optParamCheckbox,'Value',useTf);
            set(handles.paramLbText,'String',lbStr);
            set(handles.paramUbText,'String',ubStr);

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            optParamCheckbox_Callback(app, evt);

            frame = termCond.frame;
            if(isempty(frame))
                frame = LvdData.getDefaultInitialBodyInfo(event.script.lvdData.celBodyData).getBodyCenteredInertialFrame();
                termCond.frame = frame;
            end
            setappdata(handles.lvd_editEvtTermCond,'frame',frame);

            handles.refFrameTypeCombo.String = ReferenceFrameEnum.getListBoxStr();
            [ind, ~] = ReferenceFrameEnum.getIndForName(frame.typeEnum.name);
            handles.refFrameTypeCombo.Value = ind;
            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', frame.getNameStr());
            handles.refFrameNameLabel.String = sprintf('%s', frame.getNameStr());
            handles.refFrameNameLabel.TooltipString = sprintf('%s', frame.getNameStr());
        end

        function updateFrameChange(app, handles)
            lvdData = getappdata(handles.lvd_editEvtTermCond,'lvdData');
            celBodyData = lvdData.celBodyData;

            refEnumListBoxStr = ReferenceFrameEnum.getListBoxStr();
            selFrameTypeInd = handles.refFrameTypeCombo.Value;
            refFrameEnum = ReferenceFrameEnum.getEnumForListboxStr(refEnumListBoxStr{selFrameTypeInd});

            %             if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
            %                 refFrameEnum = newFrame.typeEnum;
            %                 app.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
            %             end

            switch refFrameEnum
                case ReferenceFrameEnum.BodyCenteredInertial
                    bodyInfo = getSelectedBodyInfo(app, handles);

                    newFrame = bodyInfo.getBodyCenteredInertialFrame();

                case ReferenceFrameEnum.BodyFixedRotating
                    bodyInfo = getSelectedBodyInfo(app, handles);

                    newFrame = bodyInfo.getBodyFixedFrame();

                case ReferenceFrameEnum.TwoBodyRotating
                    bodyInfo = getSelectedBodyInfo(app, handles);
                    if(not(isempty(bodyInfo.childrenBodyInfo)))
                        primaryBody = bodyInfo;
                        secondaryBody = bodyInfo.childrenBodyInfo(1);
                    else
                        primaryBody = bodyInfo.getParBodyInfo();
                        secondaryBody = bodyInfo;
                    end

                    originPt = TwoBodyRotatingFrameOriginEnum.Primary;

                    newFrame = TwoBodyRotatingFrame(primaryBody, secondaryBody, originPt, celBodyData);

                case ReferenceFrameEnum.UserDefined
                    numFrames = lvdData.geometry.refFrames.getNumRefFrames();
                    if(numFrames >= 1)
                        geometricFrame = AbstractGeometricRefFrame.empty(1,0);
                        for(i=1:numFrames) %#ok<*NO4LP> 
                            frame = lvdData.geometry.refFrames.getRefFrameAtInd(i);
                            if(frame.isVehDependent() == false)
                                geometricFrame = frame;
                                break;
                            end
                        end

                        if(not(isempty(geometricFrame)))
                            newFrame = UserDefinedGeometricFrame(geometricFrame, lvdData);
                        else
                            bodyInfo = getSelectedBodyInfo(app, handles);
                            newFrame = bodyInfo.getBodyCenteredInertialFrame();

                            warndlg('There are no geometric frames available which are not dependent on vehicle properties.  A body-centered inertial frame will be selected instead.');
                        end
                    else
                        bodyInfo = getSelectedBodyInfo(app, handles);
                        newFrame = bodyInfo.getBodyCenteredInertialFrame();

                        warndlg('There are no geometric frames available which are not dependent on vehicle properties.  A body-centered inertial frame will be selected instead.');
                    end

                otherwise
                    error('Unknown reference frame type: %s', string(refFrameEnum));
            end

            if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
                refFrameEnum = newFrame.typeEnum;
                handles.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
            end

            setappdata(handles.lvd_editEvtTermCond,'frame', newFrame);
            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', newFrame.getNameStr());
            handles.refFrameNameLabel.String = sprintf('%s', newFrame.getNameStr());
            handles.refFrameNameLabel.TooltipString = sprintf('%s', newFrame.getNameStr());
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            value = str2double(get(handles.numParamText,'String'));
            enteredStr = get(handles.numParamText,'String');
            numberName = get(handles.numParamLabel,'String');
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            lwrBndValue = str2double(get(handles.paramLbText,'String'));
            enteredStr = get(handles.paramLbText,'String');
            numberName = [get(handles.numParamLabel,'String'), ' Lower Bound'];
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(lwrBndValue, numberName, lb, ub, isInt, errMsg, enteredStr);

            uprBndValue = str2double(get(handles.paramUbText,'String'));
            enteredStr = get(handles.paramUbText,'String');
            numberName = [get(handles.numParamLabel,'String'), ' Upper Bound'];
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(uprBndValue, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                uprBndValue = str2double(get(handles.paramUbText,'String'));
                enteredStr = get(handles.paramUbText,'String');
                numberName = [get(handles.numParamLabel,'String'), ' Upper Bound'];
                lb = lwrBndValue;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(uprBndValue, numberName, lb, ub, isInt, errMsg, enteredStr);
            end
        end

        
        function saveTerminationCondition(app)
            [~, ~, handles] = convertToGUIDECallbackArguments(app);
            hObject = handles.lvd_editEvtTermCond;
            
            event = getappdata(hObject,'event');
            lv = event.script.lvdData.launchVehicle;
            
            paramValue = str2double(get(handles.numParamText,'String'));
            
            termCond = event.termCond;
            params = getParamsForSelectedTermCondType(app, handles);
           
            optVar = termCond.getExistingOptVar();
            if(not(isempty(optVar)))
                lv.lvdData.optimizer.vars.removeVariable(optVar);%need to remove existing var if it exists
            end
            
            if(strcmpi(params.paramUnit,'deg'))
                paramValue = deg2rad(paramValue);
            elseif(strcmpi(params.paramUnit,'%'))
                paramValue = paramValue/100;
            end
            
            [~,stages] = lv.getStagesListBoxStr();
            [~,tanks] = lv.getTanksListBoxStr();
            [~,engines] = lv.getEnginesListBoxStr();
            [~,stopwatches] = lv.getStopwatchesListBoxStr();
            
            if(not(isempty(stages)))
                stage = stages(get(handles.refStageCombo,'Value'));
            else
                stage = stages;
            end
            
            if(not(isempty(tanks)))
                tank = tanks(get(handles.refTankCombo,'Value'));
            else
                tank = tanks;
            end
            
            if(not(isempty(engines)))
                engine = engines(get(handles.refEngineCombo,'Value'));
            else
                engine = engines;
            end
            
            if(not(isempty(stopwatches)))
                stopwatch = stopwatches(get(handles.refStopwatchCombo,'Value'));
            else
                stopwatch = stopwatches;
            end
            
            [m,~] = enumeration('TerminationConditionEnum');
            contents = cellstr(get(handles.termCondTypeCombo,'String'));
            termCondType = contents{get(handles.termCondTypeCombo,'Value')};
            ind = strcmpi({m.nameStr},termCondType);
            termCond = feval(sprintf('%s.getTermCondForParams',m(ind).classNameStr), paramValue, stage, tank, engine, stopwatch);
                    
            lb = str2double(get(handles.paramLbText,'String'));
            ub = str2double(get(handles.paramUbText,'String'));
            
            if(strcmpi(params.paramUnit,'deg'))
                lb = deg2rad(lb);
                ub = deg2rad(ub);
            elseif(strcmpi(params.paramUnit,'%'))
                lb = lb/100;
                ub = ub/100;
            end
            
            optVar = termCond.getNewOptVar();
            
            if(not(isempty(optVar)))
                optVar.setUseTfForVariable(true); 
                optVar.setBndsForVariable(lb, ub);
    
                useTf = logical(get(handles.optParamCheckbox,'Value'));
                optVar.setUseTfForVariable(useTf);       
    
                termCond.optVar = optVar;
                optVar.varObj = termCond;
                
                lv.lvdData.optimizer.vars.addVariable(optVar);
            end
            
            frame = getappdata(hObject,'frame');
            termCond.frame = frame;
            
            event.termCond = termCond;
            
            close(app.lvd_editEvtTermCond);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_editEvtTermCond_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            centerUIFigure(app.lvd_editEvtTermCond);
            applySelectedThemeToApp(app);
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_editEvtTermCond (see VARARGIN)

            % Choose default command line output for lvd_editEvtTermCond
            handles.output = hObject;

            event = varargin{1};
            setappdata(hObject,'event',event);
            setappdata(hObject,'lvdData',event.script.lvdData);

            populateGUI(app, handles, event);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_editEvtTermCond wait for user response (see UIRESUME)
            uiwait(handles.lvd_editEvtTermCond);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_editEvtTermCond);
        end

        % Window key release function: lvd_editEvtTermCond
        function lvd_editEvtTermCond_WindowKeyReleaseFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_editEvtTermCond (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was released, in lower case
            %	Character: character interpretation of the key(s) that was released
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) released
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_editEvtTermCond);
            end
        end

        % Value changed function: numParamText
        function numParamText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to numParamText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of numParamText as text
            %        str2double(get(hObject,'String')) returns contents of numParamText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: optParamCheckbox
        function optParamCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.optParamCheckbox;

            % hObject    handle to optParamCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of optParamCheckbox
            if(get(hObject,'Value') == 1)
                set(handles.paramLbText,'Enable','on');
                set(handles.paramUbText,'Enable','on');
            else
                set(handles.paramLbText,'Enable','off');
                set(handles.paramUbText,'Enable','off');
            end
        end

        % Value changed function: paramLbText
        function paramLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to paramLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of paramLbText as text
            %        str2double(get(hObject,'String')) returns contents of paramLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: paramUbText
        function paramUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to paramUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of paramUbText as text
            %        str2double(get(hObject,'String')) returns contents of paramUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: refFrameTypeCombo
        function refFrameTypeCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to refFrameTypeCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns refFrameTypeCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from refFrameTypeCombo
            updateFrameChange(app, handles);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                app.saveTerminationCondition();
            else
                msgbox(errMsg,'Errors were found while editing the termination conditions.','error');
            end
        end

        % Button pushed function: setFrameOptionsButton
        function setFrameOptionsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to setFrameOptionsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            curFrame = getappdata(handles.lvd_editEvtTermCond,'frame');
            curFrame = curFrame.editFrameDialogUI(EditReferenceFrameContextEnum.ForState);

            setappdata(handles.lvd_editEvtTermCond,'frame',curFrame);

            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', curFrame.getNameStr());
            handles.refFrameNameLabel.String = sprintf('%s', curFrame.getNameStr());
            handles.refFrameNameLabel.TooltipString = sprintf('%s', curFrame.getNameStr());
        end

        % Value changed function: termCondTypeCombo
        function termCondTypeCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to termCondTypeCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns termCondTypeCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from termCondTypeCombo
            params = getParamsForSelectedTermCondType(app, handles);

            set(handles.numParamLabel,'String',params.paramName);
            set(handles.numParamUnitLabel,'String',params.paramUnit);

            set(handles.numParamText,'Enable',params.useParam);
            set(handles.optParamCheckbox,'Enable',params.useParam);
            set(handles.paramLbText,'Enable',params.useParam);
            set(handles.paramUbText,'Enable',params.useParam);

            if(params.useParam == false)
                set(handles.optParamCheckbox,'Value',0);
            end

            set(handles.refStageCombo,'Enable',params.useStages);
            set(handles.refTankCombo,'Enable',params.useTanks);
            set(handles.refEngineCombo,'Enable',params.useEngines);
            set(handles.refStopwatchCombo,'Enable',params.useStopwatches);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_editEvtTermCond = uifigure('Visible', 'off');
            app.lvd_editEvtTermCond.Name = 'Edit Termination Condition';
            app.lvd_editEvtTermCond.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_editEvtTermCond.WindowKeyReleaseFcn = createCallbackFcn(app, @lvd_editEvtTermCond_WindowKeyReleaseFcn, true);
            app.lvd_editEvtTermCond.Tag = 'lvd_editEvtTermCond';
            app.GridLayout = uigridlayout(app.lvd_editEvtTermCond);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Termination Condition';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.termCondTypeCombo = uidropdown(app.GridLayout3);
            app.termCondTypeCombo.Items = {'None'};
            app.termCondTypeCombo.ValueChangedFcn = createCallbackFcn(app, @termCondTypeCombo_Callback, true);
            app.termCondTypeCombo.Tag = 'termCondTypeCombo';
            app.termCondTypeCombo.Value = 'None';
            app.ParametersPanel = uipanel(app.GridLayout3);
            app.ParametersPanel.Title = 'Parameters';
            app.GridLayout6 = uigridlayout(app.ParametersPanel);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.numParamLabel = uilabel(app.GridLayout7);
            app.numParamLabel.Tag = 'numParamLabel';
            app.numParamLabel.Text = 'NumParam';
            app.numParamText = uieditfield(app.GridLayout7, 'text');
            app.numParamText.ValueChangedFcn = createCallbackFcn(app, @numParamText_Callback, true);
            app.numParamText.Tag = 'numParamText';
            app.numParamText.Value = '0.0';
            app.numParamUnitLabel = uilabel(app.GridLayout7);
            app.numParamUnitLabel.Tag = 'numParamUnitLabel';
            app.numParamUnitLabel.Text = 'sec';
            app.optParamCheckbox = uicheckbox(app.GridLayout7);
            app.optParamCheckbox.ValueChangedFcn = createCallbackFcn(app, @optParamCheckbox_Callback, true);
            app.optParamCheckbox.Tag = 'optParamCheckbox';
            app.optParamCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.optParamCheckbox.Text = 'Opt?';
            app.optParamCheckbox.Value = true;
            app.paramLbText = uieditfield(app.GridLayout7, 'text');
            app.paramLbText.ValueChangedFcn = createCallbackFcn(app, @paramLbText_Callback, true);
            app.paramLbText.Tag = 'paramLbText';
            app.paramLbText.Tooltip = 'Parameter lower bound.';
            app.paramLbText.Value = '0.0';
            app.paramUbText = uieditfield(app.GridLayout7, 'text');
            app.paramUbText.ValueChangedFcn = createCallbackFcn(app, @paramUbText_Callback, true);
            app.paramUbText.Tag = 'paramUbText';
            app.paramUbText.Tooltip = 'Parameter upper bound.';
            app.paramUbText.Value = '0.0';
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.text6 = uilabel(app.GridLayout8);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Ref. Stage';
            app.text7 = uilabel(app.GridLayout8);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Ref. Tank';
            app.text8 = uilabel(app.GridLayout8);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Ref. Engine';
            app.text9 = uilabel(app.GridLayout8);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Ref. Stopwatch';
            app.refStageCombo = uidropdown(app.GridLayout8);
            app.refStageCombo.Items = {'Pop-up Menu'};
            app.refStageCombo.Tag = 'refStageCombo';
            app.refStageCombo.Value = 'Pop-up Menu';
            app.refTankCombo = uidropdown(app.GridLayout8);
            app.refTankCombo.Items = {'Pop-up Menu'};
            app.refTankCombo.Tag = 'refTankCombo';
            app.refTankCombo.Value = 'Pop-up Menu';
            app.refEngineCombo = uidropdown(app.GridLayout8);
            app.refEngineCombo.Items = {'Pop-up Menu'};
            app.refEngineCombo.Tag = 'refEngineCombo';
            app.refEngineCombo.Value = 'Pop-up Menu';
            app.refStopwatchCombo = uidropdown(app.GridLayout8);
            app.refStopwatchCombo.Items = {'Pop-up Menu'};
            app.refStopwatchCombo.Tag = 'refStopwatchCombo';
            app.refStopwatchCombo.Value = 'Pop-up Menu';
            app.ReferenceFramePanel = uipanel(app.GridLayout3);
            app.ReferenceFramePanel.Title = 'Reference Frame';
            app.GridLayout4 = uigridlayout(app.ReferenceFramePanel);
            app.refFrameNameLabel = uilabel(app.GridLayout4);
            app.refFrameNameLabel.Tag = 'refFrameNameLabel';
            app.refFrameNameLabel.Text = 'Static Text';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.text10 = uilabel(app.GridLayout5);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Frame Type';
            app.refFrameTypeCombo = uidropdown(app.GridLayout5);
            app.refFrameTypeCombo.Items = {};
            app.refFrameTypeCombo.ValueChangedFcn = createCallbackFcn(app, @refFrameTypeCombo_Callback, true);
            app.refFrameTypeCombo.Tag = 'refFrameTypeCombo';
            app.refFrameTypeCombo.Tooltip = 'This is the reference frame in which the selected quantity will be evaluated in.';
            app.refFrameTypeCombo.Value = {};
            app.setFrameOptionsButton = uibutton(app.GridLayout5, 'push');
            app.setFrameOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @setFrameOptionsButton_Callback, true);
            app.setFrameOptionsButton.Tag = 'setFrameOptionsButton';
            app.setFrameOptionsButton.Icon = 'reference_frame.png';
            app.setFrameOptionsButton.Tooltip = 'Sets parameters for the selected reference frame type.';
            app.setFrameOptionsButton.Text = 'Set Frame Options';
            % Show the figure after all components are created
            app.lvd_editEvtTermCond.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_editEvtTermCond_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_editEvtTermCond)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_editEvtTermCond_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_editEvtTermCond)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_editEvtTermCond)
        end
    end
end