classdef lvd_editNonSeqEventGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_editNonSeqEventGUI  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        GridLayout2             matlab.ui.container.GridLayout
        NonSequentialEventPropertiesPanel  matlab.ui.container.Panel
        GridLayout4             matlab.ui.container.GridLayout
        GridLayout5             matlab.ui.container.GridLayout
        maxExecsText            matlab.ui.control.EditField
        text6                   matlab.ui.control.Label
        uprBndEvtCombo          matlab.ui.control.DropDown
        lwrBndEvtCombo          matlab.ui.control.DropDown
        text5                   matlab.ui.control.Label
        text4                   matlab.ui.control.Label
        EventPanel              matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        editEventButton         matlab.ui.control.Button
        eventNameLabel          matlab.ui.control.Label
        saveAndCloseButton      matlab.ui.control.Button
        text2                   matlab.ui.control.Label
    end


    methods (Access = private)
        function populateGUI(app, nonSeqEvt, lvdData, handles)
            handles.eventNameLabel.String = nonSeqEvt.evt.name;

            listBoxStr = vertcat(' ',lvdData.script.getListboxStr());
            handles.lwrBndEvtCombo.String = listBoxStr;

            if(not(isempty(nonSeqEvt.lwrBndEvt)))
                handles.lwrBndEvtCombo.Value = lvdData.script.getNumOfEvent(nonSeqEvt.lwrBndEvt) + 1;
            else
                handles.lwrBndEvtCombo.Value = 1;
            end

            listBoxStr = vertcat(' ',lvdData.script.getListboxStr());
            handles.uprBndEvtCombo.String = listBoxStr;

            if(not(isempty(nonSeqEvt.uprBndEvt)))
                handles.uprBndEvtCombo.Value = lvdData.script.getNumOfEvent(nonSeqEvt.uprBndEvt) + 1;
            else
                handles.uprBndEvtCombo.Value = 1;
            end

            handles.maxExecsText.String = num2str(nonSeqEvt.maxNumExecs);
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            value = str2double(get(handles.maxExecsText,'String'));
            enteredStr = get(handles.maxExecsText,'String');
            numberName = 'Max. Number of Executions';
            lb = 1;
            ub = Inf;
            isInt = true;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);
        end

        
        function saveNonSeqEvt(app)
            [~, ~, handles] = convertToGUIDECallbackArguments(app);
            
            lvdData = getappdata(handles.lvd_editNonSeqEventGUI, 'lvdData');
            nonSeqEvt = getappdata(handles.lvd_editNonSeqEventGUI, 'nonSeqEvt');
            
            lwrBndValue = handles.lwrBndEvtCombo.Value;
            if(lwrBndValue == 1)
                lwrBndEvt = LaunchVehicleEvent.empty(1,0);
            else
                lwrBndEvtNum = lwrBndValue - 1;
                lwrBndEvt = lvdData.script.getEventForInd(lwrBndEvtNum);
            end
            nonSeqEvt.lwrBndEvt = lwrBndEvt;
            
            uprBndValue = handles.uprBndEvtCombo.Value;
            if(uprBndValue == 1)
                uprBndEvt = LaunchVehicleEvent.empty(1,0);
            else
                uprBndEvtNum = uprBndValue - 1;
                uprBndEvt = lvdData.script.getEventForInd(uprBndEvtNum);
            end
            nonSeqEvt.uprBndEvt = uprBndEvt;
            
            nonSeqEvt.maxNumExecs = str2double(handles.maxExecsText.String);
            nonSeqEvt.numExecsRemaining = str2double(handles.maxExecsText.String);
            
            close(app.lvd_editNonSeqEventGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_editNonSeqEventGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            centerUIFigure(app.lvd_editNonSeqEventGUI);
            applySelectedThemeToApp(app);
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_editNonSeqEventGUI (see VARARGIN)

            % Choose default command line output for lvd_editNonSeqEventGUI
            handles.output = hObject;

            nonSeqEvt = varargin{1};
            setappdata(hObject, 'nonSeqEvt', nonSeqEvt);

            lvdData = varargin{2};
            setappdata(hObject, 'lvdData', lvdData);

            populateGUI(app, nonSeqEvt, lvdData, handles);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_editNonSeqEventGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_editNonSeqEventGUI);
        end

        % Button pushed function: editEventButton
        function editEventButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editEventButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            nonSeqEvt = getappdata(handles.lvd_editNonSeqEventGUI, 'nonSeqEvt');
            event = nonSeqEvt.evt;

            %     lvd_editEventGUI(event, true);
            output = AppDesignerGUIOutput({false});
            lvd_editEventGUI_App(event, true, output);

            handles.eventNameLabel.String = nonSeqEvt.evt.name;
        end

        % Value changed function: maxExecsText
        function maxExecsText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to maxExecsText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of maxExecsText as text
            %        str2double(get(hObject,'String')) returns contents of maxExecsText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                app.saveNonSeqEvt();
            else
                msgbox(errMsg,'Errors were found while editing the non-sequential event.','error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_editNonSeqEventGUI = uifigure('Visible', 'off');
            app.lvd_editNonSeqEventGUI.Name = 'Edit Non-Sequential Event';
            app.lvd_editNonSeqEventGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_editNonSeqEventGUI.Tag = 'lvd_editNonSeqEventGUI';
            app.GridLayout = uigridlayout(app.lvd_editNonSeqEventGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Non-Sequential Event';
            app.saveAndCloseButton = uibutton(app.GridLayout, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.EventPanel = uipanel(app.GridLayout2);
            app.EventPanel.Title = 'Event';
            app.GridLayout3 = uigridlayout(app.EventPanel);
            app.eventNameLabel = uilabel(app.GridLayout3);
            app.eventNameLabel.Tag = 'eventNameLabel';
            app.eventNameLabel.Text = 'Event Name';
            app.editEventButton = uibutton(app.GridLayout3, 'push');
            app.editEventButton.ButtonPushedFcn = createCallbackFcn(app, @editEventButton_Callback, true);
            app.editEventButton.Tag = 'editEventButton';
            app.editEventButton.Icon = 'nonseq_event.png';
            app.editEventButton.Text = 'Edit Event';
            app.NonSequentialEventPropertiesPanel = uipanel(app.GridLayout2);
            app.NonSequentialEventPropertiesPanel.Title = 'Non-Sequential Event Properties';
            app.GridLayout4 = uigridlayout(app.NonSequentialEventPropertiesPanel);
            app.text4 = uilabel(app.GridLayout4);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Activation Lower Bound';
            app.text5 = uilabel(app.GridLayout4);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Activation Upper Bound';
            app.lwrBndEvtCombo = uidropdown(app.GridLayout4);
            app.lwrBndEvtCombo.Items = {'Pop-up Menu'};
            app.lwrBndEvtCombo.Tag = 'lwrBndEvtCombo';
            app.lwrBndEvtCombo.Tooltip = 'This non-sequential event will only execute after the start of this event in the mission script.  Set empty to allow all events before the upper bound event.';
            app.lwrBndEvtCombo.Value = 'Pop-up Menu';
            app.uprBndEvtCombo = uidropdown(app.GridLayout4);
            app.uprBndEvtCombo.Items = {'Pop-up Menu'};
            app.uprBndEvtCombo.Tag = 'uprBndEvtCombo';
            app.uprBndEvtCombo.Tooltip = 'This non-sequential event will only execute before the end of this event in the mission script.  Set empty to allow all events afer the lower bound event.';
            app.uprBndEvtCombo.Value = 'Pop-up Menu';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.text6 = uilabel(app.GridLayout5);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Max. Executions';
            app.maxExecsText = uieditfield(app.GridLayout5, 'text');
            app.maxExecsText.ValueChangedFcn = createCallbackFcn(app, @maxExecsText_Callback, true);
            app.maxExecsText.Tag = 'maxExecsText';
            app.maxExecsText.Tooltip = 'The number of times this non-sequential event can exceute.  Must be set to an integer greater than 0.';
            app.maxExecsText.Value = '0.0';
            % Show the figure after all components are created
            app.lvd_editNonSeqEventGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_editNonSeqEventGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_editNonSeqEventGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_editNonSeqEventGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_editNonSeqEventGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_editNonSeqEventGUI)
        end
    end
end