classdef lvd_EditGeometricAnglesGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditGeometricAnglesGUI  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        Panel                       matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        GridLayout3                 matlab.ui.container.GridLayout
        removeAngleButton           matlab.ui.control.Button
        addAngleButton              matlab.ui.control.Button
        anglesListBox               matlab.ui.control.ListBox
        anglesSearchText            matlab.ui.control.EditField
        closeButton                 matlab.ui.control.Button
        text2                       matlab.ui.control.Label
    end

    methods (Access = private)
        function populateGUI(app, handles, lvdData)
            [~, ~] = app.setListboxStr();
        end
        
        function [listBoxStr, angles] = setListboxStr(app, query)
            if(nargin < 2)
                query = app.anglesSearchText.Value;
            end
            
            hFig = app.lvd_EditGeometricAnglesGUI;
            currentValue = app.anglesListBox.Value;
            hadSelection = isa(currentValue, 'AbstractGeometricAngle');
            if(hadSelection)
                setappdata(hFig, 'h8Selected', currentValue);
            end
            lvdData = getappdata(app.lvd_EditGeometricAnglesGUI,'lvdData');
            [listBoxStr, angles] = lvdData.geometry.angles.getListboxStr();
            keep = lvd_filterListboxItems(query, listBoxStr);
            listBoxStr = listBoxStr(keep);
            angles = angles(keep);
            storedValue = [];
            if(isappdata(hFig, 'h8Selected'))
                storedValue = getappdata(hFig, 'h8Selected');
            end
            selectedValue = lvd_selectVisibleListValue({currentValue, storedValue}, angles);
            app.anglesListBox.ItemsData = angles;
            app.anglesListBox.Items = listBoxStr;
            if(not(isempty(selectedValue)))
                app.anglesListBox.Value = selectedValue;
                setappdata(hFig, 'h8Selected', selectedValue);
            elseif(hadSelection)
                %A visible selection that was filtered out stays cleared.
            elseif(not(isempty(angles)))
                app.anglesListBox.Value = angles(1);
                setappdata(hFig, 'h8Selected', angles(1));
            end
            
            num = numel(angles);
            if(num <= 0)
                app.removeAngleButton.Enable = 'off';
            else
                app.removeAngleButton.Enable = 'on';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditGeometricAnglesGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditGeometricAnglesGUI (see VARARGIN)

            % Choose default command line output for lvd_EditGeometricAnglesGUI
            handles.output = hObject;
            
            centerUIFigure(app.lvd_EditGeometricAnglesGUI);
            applySelectedThemeToApp(app);

            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            populateGUI(app, handles, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditGeometricAnglesGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditGeometricAnglesGUI);
        end

        % Button pushed function: addAngleButton
        function addAngleButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addAngleButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditGeometricAnglesGUI,'lvdData');

            listboxStr = GeometricAngleEnum.getListBoxStr();
            out = AppDesignerGUIOutput();
            listdlgARH_App('ListString', listboxStr, ...
                'SelectionMode', 'single', ...
                'ListSize', [300 300], ...
                'InitialValue',1, ...
                'Name', 'Select New Angle Type', ...
                'PromptString', 'Select the type of the angle to be created:', ...
                'out',out);
            Selection = out.output{1};
            ok = out.output{2};

            if(ok)
                enum = GeometricAngleEnum.getEnumForListboxStr(listboxStr{Selection});

                switch enum
                    case GeometricAngleEnum.AngleBetweenVectors
                        numVectors = lvdData.geometry.vectors.getNumVectors();
                        if(numVectors >= 2)
                            vector = lvdData.geometry.vectors.getVectorAtInd(1);
                            plane = lvdData.geometry.vectors.getVectorAtInd(2);
                            newAngle = TwoVectorAngle(vector, plane, 'New Angle', lvdData);

                        else
%                             errordlg('Cannot create angle between vectors: There must be at least two geometric vectors available.','Cannot Create Angle');
                            uialert(app.lvd_EditGeometricAnglesGUI, 'Cannot create angle between vectors: There must be at least two geometric vectors available.', 'Cannot Create Angle',"Modal",true);
                            return;
                        end

                    case GeometricAngleEnum.AngleBetweenVectorPlane
                        numVectors = lvdData.geometry.vectors.getNumVectors();
                        numPlanes = lvdData.geometry.planes.getNumPlanes();
                        if(numVectors >= 1 && numPlanes >= 1)
                            vector = lvdData.geometry.vectors.getVectorAtInd(1);
                            plane = lvdData.geometry.planes.getPlaneAtInd(1);
                            newAngle = VectorPlaneAngle(vector, plane, 'New Angle', lvdData);

                        else
%                             errordlg('Cannot create angle between vector and plane: There must be at least one geometric vector and one geometric plane available.','Cannot Create Angle');
                            uialert(app.lvd_EditGeometricAnglesGUI, 'Cannot create angle between vector and plane: There must be at least one geometric vector and one geometric plane available.', 'Cannot Create Angle',"Modal",true);
                            return;
                        end

                    case GeometricAngleEnum.AngleBetweenPlanes
                        numPlanes = lvdData.geometry.planes.getNumPlanes();
                        if(numPlanes >= 2)
                            plane1 = lvdData.geometry.planes.getPlaneAtInd(1);
                            plane2 = lvdData.geometry.planes.getPlaneAtInd(2);
                            newAngle = TwoPlaneAngle(plane1, plane2, 'New Angle', lvdData);

                        else
                            uialert(app.lvd_EditGeometricAnglesGUI, 'Cannot create angle between planes: There must be at least two geometric planes available.', 'Cannot Create Angle',"Modal",true);
                            return;
                        end

                    case GeometricAngleEnum.VectorDotProduct
                        numVectors = lvdData.geometry.vectors.getNumVectors();
                        if(numVectors >= 1)
                            vector1 = lvdData.geometry.vectors.getVectorAtInd(1);
                            vector2 = lvdData.geometry.vectors.getVectorAtInd(min(2, numVectors));
                            newAngle = VectorDotProductAngle(vector1, vector2, 'New Dot Product', lvdData);

                        else
                            uialert(app.lvd_EditGeometricAnglesGUI, 'Cannot create dot product: There must be at least one geometric vector available.', 'Cannot Create Angle',"Modal",true);
                            return;
                        end

                    otherwise
                        error('Unknown Angle Type: %s', enum.name)
                end

                useTF = newAngle.openEditDialog();

                if(useTF)
                    lvdData.geometry.angles.addAngle(newAngle);
                app.anglesSearchText.Value = '';
                [~, ~] = app.setListboxStr('');
                app.anglesListBox.Value = newAngle;
                end
            end
        end

        % Value changed function: anglesListBox
        function anglesListBox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to anglesListBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns anglesListBox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from anglesListBox
%             lvdData = getappdata(handles.lvd_EditGeometricAnglesGUI,'lvdData');
%             if(strcmpi(get(handles.lvd_EditGeometricAnglesGUI,'SelectionType'),'open') && ...
%                     lvdData.geometry.angles.getNumAngles() > 0)
%                 selAngle = app.anglesListBox.Value;
% 
%                 selAngle.openEditDialog();
% 
%                 populateGUI(app, handles, lvdData);
%             end
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to closeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditGeometricAnglesGUI);
        end

        % Window key press function: lvd_EditGeometricAnglesGUI
        function lvd_EditGeometricAnglesGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
 
            % hObject    handle to figure
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            switch(eventdata.Key)
                case 'return'
                    if(app.lvd_EditGeometricAnglesGUI.CurrentObject == app.anglesSearchText)
                        return;
                    end
                    close(handles.lvd_EditGeometricAnglesGUI);
                case 'enter'
                    if(app.lvd_EditGeometricAnglesGUI.CurrentObject == app.anglesSearchText)
                        return;
                    end
                    close(handles.lvd_EditGeometricAnglesGUI);
                case 'escape'
                    close(handles.lvd_EditGeometricAnglesGUI);
            end
        end

        % Button pushed function: removeAngleButton
        function removeAngleButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeAngleButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditGeometricAnglesGUI,'lvdData');

            oldInd = app.anglesListBox.ValueIndex;
            selAngle = app.anglesListBox.Value;

            tf = selAngle.isInUse(lvdData);

            if(tf == false)
                lvdData.geometry.angles.removeAngle(selAngle);

                lvdData.viewSettings.removeGeoAngleFromViewProfiles(selAngle);

                [~, visibleObjs] = app.setListboxStr(app.anglesSearchText.Value);
                if(not(isempty(visibleObjs)))
                app.anglesListBox.Value = visibleObjs(min(oldInd, numel(visibleObjs)));
                end
            else
                msg = sprintf('Could not delete the geometric angle "%s" because it is in use elsewhere.  Remove the angle dependencies before attempting to delete the angle.', selAngle.getName());
%                 warndlg(msg,'Cannot Delete Angle','modal');
                uialert(app.lvd_EditGeometricAnglesGUI, msg, 'Cannot Delete Angle',"Modal",true);
            end
        end

        % Window button down function: lvd_EditGeometricAnglesGUI
        function lvd_EditGeometricAnglesGUIWindowButtonDown(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            selType = app.lvd_EditGeometricAnglesGUI.SelectionType;
            curObj = app.lvd_EditGeometricAnglesGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.anglesListBox && numel(app.anglesListBox.ItemsData) > 0)
                    selAngle = app.anglesListBox.Value;
                    selAngle.openEditDialog();
                    
                    [~, ~] = app.setListboxStr();
                end
            end
        end

 
        % Value changing function: anglesSearchText
        function anglesSearchTextValueChanging(app, event)
            [~, ~] = app.setListboxStr(event.Value);
        end
        % Value changed function: anglesSearchText
        function anglesSearchTextValueChanged(app, event)
            [~, ~] = app.setListboxStr(event.Value);
        end
    end
    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditGeometricAnglesGUI = uifigure('Visible', 'off');
            app.lvd_EditGeometricAnglesGUI.Name = 'Edit Angles';
            app.lvd_EditGeometricAnglesGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditGeometricAnglesGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_EditGeometricAnglesGUIWindowButtonDown, true);
            app.lvd_EditGeometricAnglesGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditGeometricAnglesGUI_WindowKeyPressFcn, true);
            app.lvd_EditGeometricAnglesGUI.Tag = 'lvd_EditGeometricAnglesGUI';
            app.GridLayout = uigridlayout(app.lvd_EditGeometricAnglesGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Angles';
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Icon = 'close_window(1).png';
            app.closeButton.Text = 'Close';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.anglesSearchText = uieditfield(app.GridLayout2, 'text');
            app.anglesSearchText.ValueChangingFcn = createCallbackFcn(app, @anglesSearchTextValueChanging, true);
            app.anglesSearchText.ValueChangedFcn = createCallbackFcn(app, @anglesSearchTextValueChanged, true);
            app.anglesSearchText.Tag = 'anglesSearchText';
            app.anglesSearchText.Tooltip = 'Type to filter the angles below.';
            app.anglesSearchText.Placeholder = 'Search angles...';
            app.anglesListBox = uilistbox(app.GridLayout2);
            app.anglesListBox.Items = {'Listbox'};
            app.anglesListBox.ValueChangedFcn = createCallbackFcn(app, @anglesListBox_Callback, true);
            app.anglesListBox.Tag = 'anglesListBox';
            app.anglesListBox.Tooltip = 'Double click an entry to edit it.';
            app.anglesListBox.Value = 'Listbox';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.addAngleButton = uibutton(app.GridLayout3, 'push');
            app.addAngleButton.ButtonPushedFcn = createCallbackFcn(app, @addAngleButton_Callback, true);
            app.addAngleButton.Tag = 'addAngleButton';
            app.addAngleButton.Icon = 'add.png';
            app.addAngleButton.Text = 'Add Angle';
            app.removeAngleButton = uibutton(app.GridLayout3, 'push');
            app.removeAngleButton.ButtonPushedFcn = createCallbackFcn(app, @removeAngleButton_Callback, true);
            app.removeAngleButton.Tag = 'removeAngleButton';
            app.removeAngleButton.Icon = 'remove.png';
            app.removeAngleButton.Text = 'Remove Angle';
            % Show the figure after all components are created
            app.lvd_EditGeometricAnglesGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditGeometricAnglesGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditGeometricAnglesGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditGeometricAnglesGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditGeometricAnglesGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditGeometricAnglesGUI)
        end
    end
end