classdef lvd_EditVectorPlaneAngleGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditVectorPlaneAngleGUI  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        uipanel1                     matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        uipanel5                     matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        Image4                       matlab.ui.control.Image
        Image3                       matlab.ui.control.Image
        lineSpecCombo                matlab.ui.control.DropDown
        lineColorCombo               matlab.ui.control.DropDown
        text30                       matlab.ui.control.Label
        uipanel3                     matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        Image2                       matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        planeCombo                   matlab.ui.control.DropDown
        vectorCombo                  matlab.ui.control.DropDown
        text31                       matlab.ui.control.Label
        text5                        matlab.ui.control.Label
        uipanel2                     matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        nameText                     matlab.ui.control.EditField
        stageName                    matlab.ui.control.Label
        GridLayout2                  matlab.ui.container.GridLayout
        cancelButton                 matlab.ui.control.Button
        saveAndCloseButton           matlab.ui.control.Button
        text2                        matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, lvdData, angle)
            set(handles.nameText,'String',angle.getName());

            [vectorsListBoxStr, ~] = lvdData.geometry.vectors.getListboxStr();
            [planesListBoxStr, ~] = lvdData.geometry.planes.getListboxStr();

            handles.vectorCombo.String = vectorsListBoxStr;
            handles.vectorCombo.Value = lvdData.geometry.vectors.getIndsForVectors(angle.vector);

            handles.planeCombo.String = planesListBoxStr;
            handles.planeCombo.Value = lvdData.geometry.planes.getIndsForPlanes(angle.plane);

            lvdSetupColorPicker(app.lvd_EditVectorPlaneAngleGUI, app.GridLayout6, 2, 3, handles.lineColorCombo, lvd_colorSpecToRGB(angle.lineColor), 'The color of the angle''s line on the display.', 'lineColor');

            handles.lineSpecCombo.String = LineSpecEnum.getListboxStr();
            handles.lineSpecCombo.Value = LineSpecEnum.getIndForName(angle.lineSpec.name);
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            if(isempty(strtrim(handles.nameText.String)))
                errMsg{end+1} = 'Angle name must contain more than white space and must not be empty.';
            end
        end

        function saveAndClose(app, handles)
            lvdData = getappdata(handles.lvd_EditVectorPlaneAngleGUI, 'lvdData');
            angle = getappdata(handles.lvd_EditVectorPlaneAngleGUI, 'angle');

            angle.setName(handles.nameText.String);

            angle.vector = lvdData.geometry.vectors.getVectorAtInd(handles.vectorCombo.Value);
            angle.plane = lvdData.geometry.planes.getPlaneAtInd(handles.planeCombo.Value);

            angle.lineColor = lvdGetColorPickerRGB(app.lvd_EditVectorPlaneAngleGUI, 'lineColor', angle.lineColor);

            str = handles.lineSpecCombo.String{handles.lineSpecCombo.Value};
            angle.lineSpec = LineSpecEnum.getEnumForListboxStr(str);

            app.output.output = {true};
            close(handles.lvd_EditVectorPlaneAngleGUI);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditVectorPlaneAngleGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditVectorPlaneAngleGUI (see VARARGIN)

            % Choose default command line output for lvd_EditVectorPlaneAngleGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            angle = varargin{1};
            setappdata(hObject, 'angle', angle);

            lvdData = varargin{2};
            setappdata(hObject, 'lvdData', lvdData);

            app.output = varargin{3};

            populateGUI(app, handles, lvdData, angle);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditVectorPlaneAngleGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditVectorPlaneAngleGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditVectorPlaneAngleGUI);
        end

        % Window key press function: lvd_EditVectorPlaneAngleGUI
        function lvd_EditVectorPlaneAngleGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditVectorPlaneAngleGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditVectorPlaneAngleGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                %                     uiresume(handles.lvd_EditVectorPlaneAngleGUI);
                app.saveAndClose(handles);
            else
                %                     msgbox(errMsg,'Invalid Angle Inputs','error');
                uialert(app.lvd_EditVectorPlaneAngleGUI, errMsg, 'Invalid Angle Inputs', "Icon","error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditVectorPlaneAngleGUI = uifigure('Visible', 'off');
            app.lvd_EditVectorPlaneAngleGUI.Name = 'Edit Angle';
            app.lvd_EditVectorPlaneAngleGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditVectorPlaneAngleGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditVectorPlaneAngleGUI_WindowKeyPressFcn, true);
            app.lvd_EditVectorPlaneAngleGUI.Tag = 'lvd_EditVectorPlaneAngleGUI';
            app.GridLayout = uigridlayout(app.lvd_EditVectorPlaneAngleGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Angle';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Name';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4 = uigridlayout(app.uipanel2);
            app.stageName = uilabel(app.GridLayout4);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Angle Name';
            app.nameText = uieditfield(app.GridLayout4, 'text');
            app.nameText.Tag = 'nameText';
            app.nameText.Value = 'POINT NAME';
            app.uipanel3 = uipanel(app.GridLayout3);
            app.uipanel3.Title = 'Vector';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout5 = uigridlayout(app.uipanel3);
            app.text5 = uilabel(app.GridLayout5);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Vector';
            app.text31 = uilabel(app.GridLayout5);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Plane';
            app.vectorCombo = uidropdown(app.GridLayout5);
            app.vectorCombo.Items = {'Pop-up Menu'};
            app.vectorCombo.Tag = 'vectorCombo';
            app.vectorCombo.Tooltip = 'The measured angle is between this vector and the surface of the plane.';
            app.vectorCombo.Value = 'Pop-up Menu';
            app.planeCombo = uidropdown(app.GridLayout5);
            app.planeCombo.Items = {'Pop-up Menu'};
            app.planeCombo.Tag = 'planeCombo';
            app.planeCombo.Tooltip = 'The measured angle is between the vector and the surface of this plane.';
            app.planeCombo.Value = 'Pop-up Menu';
            app.Image = uiimage(app.GridLayout5);
            app.Image.ImageSource = 'green-arrow.png';
            app.Image2 = uiimage(app.GridLayout5);
            app.Image2.ImageSource = 'layers.png';
            app.uipanel5 = uipanel(app.GridLayout3);
            app.uipanel5.Title = 'Display';
            app.uipanel5.Tag = 'uipanel5';
            app.GridLayout6 = uigridlayout(app.uipanel5);
            app.text30 = uilabel(app.GridLayout6);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Angle Line';
            app.lineColorCombo = uidropdown(app.GridLayout6);
            app.lineColorCombo.Items = {'Pop-up Menu'};
            app.lineColorCombo.Tag = 'lineColorCombo';
            app.lineColorCombo.Tooltip = 'The color of the angle''s line on the display.';
            app.lineColorCombo.Value = 'Pop-up Menu';
            app.lineSpecCombo = uidropdown(app.GridLayout6);
            app.lineSpecCombo.Items = {'Pop-up Menu'};
            app.lineSpecCombo.Tag = 'lineSpecCombo';
            app.lineSpecCombo.Tooltip = 'The style of the angle''s line on the display.';
            app.lineSpecCombo.Value = 'Pop-up Menu';
            app.Image3 = uiimage(app.GridLayout6);
            app.Image3.ImageSource = 'color_wheel.png';
            app.Image4 = uiimage(app.GridLayout6);
            app.Image4.ImageSource = 'line.png';
            % Show the figure after all components are created
            app.lvd_EditVectorPlaneAngleGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditVectorPlaneAngleGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditVectorPlaneAngleGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditVectorPlaneAngleGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditVectorPlaneAngleGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditVectorPlaneAngleGUI)
        end
    end
end