classdef lvd_EditAlignedConstrainedCoordSysGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditAlignedConstrainedCoordSysGUI  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        uipanel1                  matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        uipanel3                  matlab.ui.container.Panel
        GridLayout5               matlab.ui.container.GridLayout
        Image2                    matlab.ui.control.Image
        Image                     matlab.ui.control.Image
        constrainedVectAxisCombo  matlab.ui.control.DropDown
        cVectorCombo              matlab.ui.control.DropDown
        alignedVectAxisCombo      matlab.ui.control.DropDown
        aVectorCombo              matlab.ui.control.DropDown
        text33                    matlab.ui.control.Label
        text31                    matlab.ui.control.Label
        text32                    matlab.ui.control.Label
        text5                     matlab.ui.control.Label
        uipanel2                  matlab.ui.container.Panel
        GridLayout4               matlab.ui.container.GridLayout
        coordSysNameText          matlab.ui.control.EditField
        stageName                 matlab.ui.control.Label
        GridLayout2               matlab.ui.container.GridLayout
        cancelButton              matlab.ui.control.Button
        saveAndCloseButton        matlab.ui.control.Button
        text2                     matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, lvdData, coordsys)
            set(handles.coordSysNameText,'String',coordsys.getName());

            vectorsListBoxStr = lvdData.geometry.vectors.getListboxStr();

            handles.aVectorCombo.String = vectorsListBoxStr;
            handles.aVectorCombo.Value = lvdData.geometry.vectors.getIndsForVectors(coordsys.aVector);

            handles.cVectorCombo.String = vectorsListBoxStr;
            handles.cVectorCombo.Value = lvdData.geometry.vectors.getIndsForVectors(coordsys.cVector);

            axesListBoxStr = AlignedConstrainedCoordSysAxesEnum.getListBoxStr();

            handles.alignedVectAxisCombo.String = axesListBoxStr;
            handles.alignedVectAxisCombo.Value = AlignedConstrainedCoordSysAxesEnum.getIndForName(coordsys.aVectorAxis.name);

            handles.constrainedVectAxisCombo.String = axesListBoxStr;
            handles.constrainedVectAxisCombo.Value = AlignedConstrainedCoordSysAxesEnum.getIndForName(coordsys.cVectorAxis.name);
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};
            lvdData = getappdata(handles.lvd_EditAlignedConstrainedCoordSysGUI, 'lvdData');

            aVector = lvdData.geometry.vectors.getVectorAtInd(handles.aVectorCombo.Value);
            cVector = lvdData.geometry.vectors.getVectorAtInd(handles.cVectorCombo.Value);
            if(aVector == cVector)
                errMsg{end+1} = 'The aligned vector and constrained vector must be different.';
            end

            str = handles.alignedVectAxisCombo.String{handles.alignedVectAxisCombo.Value};
            aVectorAxis = AlignedConstrainedCoordSysAxesEnum.getEnumForListboxStr(str);

            str = handles.constrainedVectAxisCombo.String{handles.constrainedVectAxisCombo.Value};
            cVectorAxis = AlignedConstrainedCoordSysAxesEnum.getEnumForListboxStr(str);
            if(aVectorAxis == cVectorAxis || ...
                    strcmpi(aVectorAxis.baseAxis, cVectorAxis.baseAxis))
                errMsg{end+1} = 'The aligned vector axis and constrained vector axis must be different and not opposite.';
            end

            if(isempty(strtrim(handles.coordSysNameText.String)))
                errMsg{end+1} = 'Coordinate system name must contain more than white space and must not be empty.';
            end
        end


        function saveAndClose(app, handles)
            lvdData = getappdata(handles.lvd_EditAlignedConstrainedCoordSysGUI, 'lvdData');
            coordsys = getappdata(handles.lvd_EditAlignedConstrainedCoordSysGUI, 'coordsys');

            coordsys.setName(handles.coordSysNameText.String);

            coordsys.aVector = lvdData.geometry.vectors.getVectorAtInd(handles.aVectorCombo.Value);
            coordsys.cVector = lvdData.geometry.vectors.getVectorAtInd(handles.cVectorCombo.Value);

            str = handles.alignedVectAxisCombo.String{handles.alignedVectAxisCombo.Value};
            coordsys.aVectorAxis = AlignedConstrainedCoordSysAxesEnum.getEnumForListboxStr(str);

            str = handles.constrainedVectAxisCombo.String{handles.constrainedVectAxisCombo.Value};
            coordsys.cVectorAxis = AlignedConstrainedCoordSysAxesEnum.getEnumForListboxStr(str);

            app.output.output = {true};
            close(handles.lvd_EditAlignedConstrainedCoordSysGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditAlignedConstrainedCoordSysGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditAlignedConstrainedCoordSysGUI (see VARARGIN)

            % Choose default command line output for lvd_EditAlignedConstrainedCoordSysGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            coordsys = varargin{1};
            setappdata(hObject, 'coordsys', coordsys);

            lvdData = varargin{2};
            setappdata(hObject, 'lvdData', lvdData);
            
            app.output = varargin{3};

            populateGUI(app, handles, lvdData, coordsys);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditAlignedConstrainedCoordSysGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditAlignedConstrainedCoordSysGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditAlignedConstrainedCoordSysGUI);
        end

        % Window key press function: lvd_EditAlignedConstrainedCoordSysGUI
        function lvd_EditAlignedConstrainedCoordSysGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditAlignedConstrainedCoordSysGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditAlignedConstrainedCoordSysGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                %                     uiresume(handles.lvd_EditAlignedConstrainedCoordSysGUI);
                app.saveAndClose(handles)
            else
                %                     msgbox(errMsg,'Invalid Vector Inputs','error');
                uialert(app.lvd_EditAlignedConstrainedCoordSysGUI, errMsg, 'Invalid Coordinate System Inputs', "Icon","error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditAlignedConstrainedCoordSysGUI = uifigure('Visible', 'off');
            app.lvd_EditAlignedConstrainedCoordSysGUI.Name = 'Edit Coordinate System';
            app.lvd_EditAlignedConstrainedCoordSysGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditAlignedConstrainedCoordSysGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditAlignedConstrainedCoordSysGUI_WindowKeyPressFcn, true);
            app.lvd_EditAlignedConstrainedCoordSysGUI.Tag = 'lvd_EditAlignedConstrainedCoordSysGUI';
            app.GridLayout = uigridlayout(app.lvd_EditAlignedConstrainedCoordSysGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Coordinate System';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Name';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4 = uigridlayout(app.uipanel2);
            app.stageName = uilabel(app.GridLayout4);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Coord System Name';
            app.coordSysNameText = uieditfield(app.GridLayout4, 'text');
            app.coordSysNameText.Tag = 'coordSysNameText';
            app.coordSysNameText.Value = 'POINT NAME';
            app.uipanel3 = uipanel(app.GridLayout3);
            app.uipanel3.Title = 'Coordinate System';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout5 = uigridlayout(app.uipanel3);
            app.text5 = uilabel(app.GridLayout5);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Aligned Vector';
            app.text32 = uilabel(app.GridLayout5);
            app.text32.Tag = 'text32';
            app.text32.Text = 'Aligned Vector Axis';
            app.text31 = uilabel(app.GridLayout5);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Constrained Vector';
            app.text33 = uilabel(app.GridLayout5);
            app.text33.Tag = 'text33';
            app.text33.Text = 'Constrained Vector Axis';
            app.aVectorCombo = uidropdown(app.GridLayout5);
            app.aVectorCombo.Items = {'Pop-up Menu'};
            app.aVectorCombo.Tag = 'aVectorCombo';
            app.aVectorCombo.Tooltip = 'This vector will be aligned with the aligned vector axis in the base reference frame.';
            app.aVectorCombo.Value = 'Pop-up Menu';
            app.alignedVectAxisCombo = uidropdown(app.GridLayout5);
            app.alignedVectAxisCombo.Items = {'Pop-up Menu'};
            app.alignedVectAxisCombo.Tag = 'alignedVectAxisCombo';
            app.alignedVectAxisCombo.Tooltip = 'The aligned vector will be along this axis of the base reference frame.';
            app.alignedVectAxisCombo.Value = 'Pop-up Menu';
            app.cVectorCombo = uidropdown(app.GridLayout5);
            app.cVectorCombo.Items = {'Pop-up Menu'};
            app.cVectorCombo.Tag = 'cVectorCombo';
            app.cVectorCombo.Tooltip = 'This vector will be constrained to be as close as possible to the with the constrained vector axis in the base reference frame while remaining in the plane whose normal is the aligned vector.';
            app.cVectorCombo.Value = 'Pop-up Menu';
            app.constrainedVectAxisCombo = uidropdown(app.GridLayout5);
            app.constrainedVectAxisCombo.Items = {'Pop-up Menu'};
            app.constrainedVectAxisCombo.Tag = 'constrainedVectAxisCombo';
            app.constrainedVectAxisCombo.Tooltip = 'The constrained vector will be as close as possible to this axis of the base reference frame while still remaining the plane whose normal is the aligned vector.';
            app.constrainedVectAxisCombo.Value = 'Pop-up Menu';
            app.Image = uiimage(app.GridLayout5);
            app.Image.ImageSource = 'green-arrow.png';
            app.Image2 = uiimage(app.GridLayout5);
            app.Image2.ImageSource = 'red-arrow.png';
            % Show the figure after all components are created
            app.lvd_EditAlignedConstrainedCoordSysGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditAlignedConstrainedCoordSysGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditAlignedConstrainedCoordSysGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditAlignedConstrainedCoordSysGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditAlignedConstrainedCoordSysGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditAlignedConstrainedCoordSysGUI)
        end
    end
end