classdef lvd_EditGeometricCoordSysGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditGeometricCoordSysGUI  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        Panel                         matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        GridLayout3                   matlab.ui.container.GridLayout
        removeCoordSysButton          matlab.ui.control.Button
        addCoordSysButton             matlab.ui.control.Button
        coordSysListBox               matlab.ui.control.ListBox
        coordSysSearchText            matlab.ui.control.EditField
        closeButton                   matlab.ui.control.Button
        text2                         matlab.ui.control.Label
    end

    methods (Access = private)
        function populateGUI(app, handles, lvdData)
            [~, ~] = app.setListboxStr();
        end
        
        function [listBoxStr, coordSyses] = setListboxStr(app, query)
            if(nargin < 2)
                query = app.coordSysSearchText.Value;
            end
            
            hFig = app.lvd_EditGeometricCoordSysGUI;
            currentValue = app.coordSysListBox.Value;
            hadSelection = isa(currentValue, 'AbstractGeometricCoordSystem');
            if(hadSelection)
                setappdata(hFig, 'h8Selected', currentValue);
            end
            lvdData = getappdata(app.lvd_EditGeometricCoordSysGUI,'lvdData');
            [listBoxStr, coordSyses] = lvdData.geometry.coordSyses.getListboxStr();
            keep = lvd_filterListboxItems(query, listBoxStr);
            listBoxStr = listBoxStr(keep);
            coordSyses = coordSyses(keep);
            storedValue = [];
            if(isappdata(hFig, 'h8Selected'))
                storedValue = getappdata(hFig, 'h8Selected');
            end
            selectedValue = lvd_selectVisibleListValue({currentValue, storedValue}, coordSyses);
            app.coordSysListBox.ItemsData = coordSyses;
            app.coordSysListBox.Items = listBoxStr;
            if(not(isempty(selectedValue)))
                app.coordSysListBox.Value = selectedValue;
                setappdata(hFig, 'h8Selected', selectedValue);
            elseif(hadSelection)
                %A visible selection that was filtered out stays cleared.
            elseif(not(isempty(coordSyses)))
                app.coordSysListBox.Value = coordSyses(1);
                setappdata(hFig, 'h8Selected', coordSyses(1));
            end
            
            num = numel(coordSyses);
            if(num <= 0)
                app.removeCoordSysButton.Enable = 'off';
            else
                app.removeCoordSysButton.Enable = 'on';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditGeometricCoordSysGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditGeometricCoordSysGUI (see VARARGIN)

            % Choose default command line output for lvd_EditGeometricCoordSysGUI
            handles.output = hObject;
            
            centerUIFigure(app.lvd_EditGeometricCoordSysGUI);
            applySelectedThemeToApp(app);

            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            populateGUI(app, handles, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditGeometricCoordSysGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditGeometricCoordSysGUI);
        end

        % Button pushed function: addCoordSysButton
        function addCoordSysButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addCoordSysButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditGeometricCoordSysGUI,'lvdData');

            listboxStr = GeometricCoordSysEnum.getListBoxStr();
            out = AppDesignerGUIOutput();
            listdlgARH_App('ListString', listboxStr, ...
                'SelectionMode', 'single', ...
                'ListSize', [300 300], ...
                'InitialValue',1, ...
                'Name', 'Select New Coordinate System Type', ...
                'PromptString', 'Select the type of the coordinate system to be created:', ...
                'out',out);
            Selection = out.output{1};
            ok = out.output{2};

            if(ok)
                enum = GeometricCoordSysEnum.getEnumForListboxStr(listboxStr{Selection});

                switch enum
                    case GeometricCoordSysEnum.AlignedConstrained
                        numVectors = lvdData.geometry.vectors.getNumVectors();
                        if(numVectors >= 2)
                            aVector = lvdData.geometry.vectors.getVectorAtInd(1);
                            cVector = lvdData.geometry.vectors.getVectorAtInd(2);
                            newCoordSys = AlignedConstrainedCoordSystem(aVector, AlignedConstrainedCoordSysAxesEnum.PosX, cVector, AlignedConstrainedCoordSysAxesEnum.PosZ, 'New Coordinate System', lvdData);

                        else
%                             errordlg('Cannot create Aligned/Constrained Coordinate System: There must be at least two unique geometric vectors available.','Cannot Create Coordinate System');
                            uialert(app.lvd_EditGeometricCoordSysGUI, 'Cannot create Aligned/Constrained Coordinate System: There must be at least two unique geometric vectors available.', 'Cannot Create Coordinate System',"Modal",true);
                            return;
                        end

                    case GeometricCoordSysEnum.ParallelToFrame
                        bodyInfo = lvdData.getDefaultInitialBodyInfo(lvdData.celBodyData);
                        frame = bodyInfo.getBodyCenteredInertialFrame();
                        newCoordSys = ParallelToFrameCoordSystem(frame, 'New Coordinate System', lvdData);

                    case GeometricCoordSysEnum.ParallelToFrameAtTime
                        bodyInfo = lvdData.getDefaultInitialBodyInfo(lvdData.celBodyData);
                        frame = bodyInfo.getBodyCenteredInertialFrame();
                        newCoordSys = ParallelToFrameAtTimeCoordSystem(frame, 0, 'New Coordinate System', lvdData);

                    case GeometricCoordSysEnum.ThreePoint
                        numPoints = lvdData.geometry.points.getNumPoints();
                        if(numPoints >= 3)
                            originPoint = lvdData.geometry.points.getPointAtInd(1);
                            primaryAxisPoint = lvdData.geometry.points.getPointAtInd(2);
                            planePoint = lvdData.geometry.points.getPointAtInd(3);
                            newCoordSys = ThreePointCoordSystem(originPoint, primaryAxisPoint, planePoint, 'New Coordinate System', lvdData);

                        else
                            uialert(app.lvd_EditGeometricCoordSysGUI, 'Cannot create Three Point Coordinate System: There must be at least three geometric points available.', 'Cannot Create Coordinate System',"Modal",true);
                            return;
                        end

                    otherwise
                        error('Unknown Coordinate System Type: %s', enum.name)
                end

                useTF = newCoordSys.openEditDialog();

                if(useTF)
                    lvdData.geometry.coordSyses.addCoordSys(newCoordSys);
                app.coordSysSearchText.Value = '';
                [~, ~] = app.setListboxStr('');
                app.coordSysListBox.Value = newCoordSys;
                end
            end
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to closeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditGeometricCoordSysGUI);
        end

        % Value changed function: coordSysListBox
        function coordSysListBox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to coordSysListBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns coordSysListBox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from coordSysListBox
%             lvdData = getappdata(handles.lvd_EditGeometricCoordSysGUI,'lvdData');
%             if(strcmpi(get(handles.lvd_EditGeometricCoordSysGUI,'SelectionType'),'open') && ...
%                     lvdData.geometry.coordSyses.getNumCoordSyses() > 0)
%                 selCoordSys = app.coordSysListBox.Value;
%                 selCoordSys.openEditDialog();
% 
%                 populateGUI(app, handles, lvdData);
%             end
        end

        % Window key press function: lvd_EditGeometricCoordSysGUI
        function lvd_EditGeometricCoordSysGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
 
            % hObject    handle to figure
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            switch(eventdata.Key)
                case 'return'
                    if(app.lvd_EditGeometricCoordSysGUI.CurrentObject == app.coordSysSearchText)
                        return;
                    end
                    close(handles.lvd_EditGeometricCoordSysGUI);
                case 'enter'
                    if(app.lvd_EditGeometricCoordSysGUI.CurrentObject == app.coordSysSearchText)
                        return;
                    end
                    close(handles.lvd_EditGeometricCoordSysGUI);
                case 'escape'
                    close(handles.lvd_EditGeometricCoordSysGUI);
            end
        end

        % Button pushed function: removeCoordSysButton
        function removeCoordSysButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeCoordSysButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditGeometricCoordSysGUI,'lvdData');

            oldInd = app.coordSysListBox.ValueIndex;
            selCoordSys = app.coordSysListBox.Value;

            tf = selCoordSys.isInUse(lvdData);

            if(tf == false)
                lvdData.geometry.coordSyses.removeCoordSys(selCoordSys);

                [~, visibleObjs] = app.setListboxStr(app.coordSysSearchText.Value);
                if(not(isempty(visibleObjs)))
                app.coordSysListBox.Value = visibleObjs(min(oldInd, numel(visibleObjs)));
                end
            else
                msg = sprintf('Could not delete the geometric coordinate system "%s" because it is in use as part of a reference frame.  Remove the coordinate system dependencies before attempting to delete the coordinate system.', selCoordSys.getName());
%                 warndlg(msg,'Cannot Delete Coordinate System','modal');
                uialert(app.lvd_EditGeometricCoordSysGUI, msg, 'Cannot Delete Coordinate System',"Modal",true);
            end
        end

        % Window button down function: lvd_EditGeometricCoordSysGUI
        function lvd_EditGeometricCoordSysGUIWindowButtonDown(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            selType = app.lvd_EditGeometricCoordSysGUI.SelectionType;
            curObj = app.lvd_EditGeometricCoordSysGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.coordSysListBox && numel(app.coordSysListBox.ItemsData) > 0)
                    selCoordSys = app.coordSysListBox.Value;
                    selCoordSys.openEditDialog();
    
                    [~, ~] = app.setListboxStr();
                end
            end
        end

 
        % Value changing function: coordSysSearchText
        function coordSysSearchTextValueChanging(app, event)
            [~, ~] = app.setListboxStr(event.Value);
        end
        % Value changed function: coordSysSearchText
        function coordSysSearchTextValueChanged(app, event)
            [~, ~] = app.setListboxStr(event.Value);
        end
    end
    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditGeometricCoordSysGUI = uifigure('Visible', 'off');
            app.lvd_EditGeometricCoordSysGUI.Name = 'Edit Coordinate Systems';
            app.lvd_EditGeometricCoordSysGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditGeometricCoordSysGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_EditGeometricCoordSysGUIWindowButtonDown, true);
            app.lvd_EditGeometricCoordSysGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditGeometricCoordSysGUI_WindowKeyPressFcn, true);
            app.lvd_EditGeometricCoordSysGUI.Tag = 'lvd_EditGeometricCoordSysGUI';
            app.GridLayout = uigridlayout(app.lvd_EditGeometricCoordSysGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Coordinate Systems';
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Icon = 'close_window(1).png';
            app.closeButton.Text = 'Close';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.coordSysSearchText = uieditfield(app.GridLayout2, 'text');
            app.coordSysSearchText.ValueChangingFcn = createCallbackFcn(app, @coordSysSearchTextValueChanging, true);
            app.coordSysSearchText.ValueChangedFcn = createCallbackFcn(app, @coordSysSearchTextValueChanged, true);
            app.coordSysSearchText.Tag = 'coordSysSearchText';
            app.coordSysSearchText.Tooltip = 'Type to filter the coordinate systems below.';
            app.coordSysSearchText.Placeholder = 'Search coordinate systems...';
            app.coordSysListBox = uilistbox(app.GridLayout2);
            app.coordSysListBox.Items = {'Listbox'};
            app.coordSysListBox.ValueChangedFcn = createCallbackFcn(app, @coordSysListBox_Callback, true);
            app.coordSysListBox.Tag = 'coordSysListBox';
            app.coordSysListBox.Tooltip = 'Double click an entry to edit it.';
            app.coordSysListBox.Value = 'Listbox';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.addCoordSysButton = uibutton(app.GridLayout3, 'push');
            app.addCoordSysButton.ButtonPushedFcn = createCallbackFcn(app, @addCoordSysButton_Callback, true);
            app.addCoordSysButton.Tag = 'addCoordSysButton';
            app.addCoordSysButton.Icon = 'add.png';
            app.addCoordSysButton.Text = 'Add Coord Sys';
            app.removeCoordSysButton = uibutton(app.GridLayout3, 'push');
            app.removeCoordSysButton.ButtonPushedFcn = createCallbackFcn(app, @removeCoordSysButton_Callback, true);
            app.removeCoordSysButton.Tag = 'removeCoordSysButton';
            app.removeCoordSysButton.Icon = 'remove.png';
            app.removeCoordSysButton.Text = 'Remove Coord Sys';
            % Show the figure after all components are created
            app.lvd_EditGeometricCoordSysGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditGeometricCoordSysGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditGeometricCoordSysGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditGeometricCoordSysGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditGeometricCoordSysGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditGeometricCoordSysGUI)
        end
    end
end