classdef lvd_EditParallelToFrameAtTimeCoordSysGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditFixedInFrameVectorGUI  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        uipanel1                       matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        uipanel3                       matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
        text22                         matlab.ui.control.Label
        refFrameTypeCombo              matlab.ui.control.DropDown
        setFrameOptionsButton          matlab.ui.control.Button
        uipanel2                       matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        GridLayout6                    matlab.ui.container.GridLayout
        TimeText                       matlab.ui.control.EditField
        text11                         matlab.ui.control.Label
        vectorNameLabel_2              matlab.ui.control.Label
        coordSysNameText               matlab.ui.control.EditField
        vectorNameLabel                matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        cancelButton                   matlab.ui.control.Button
        saveAndCloseButton             matlab.ui.control.Button
        text2                          matlab.ui.control.Label
        ContextMenu                    matlab.ui.container.ContextMenu
        EnterUTasDateTimeMenu          matlab.ui.container.Menu
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function frameUpdated(app, handles)
            lvdData = getappdata(handles.lvd_EditFixedInFrameVectorGUI, 'lvdData');

            frame = getappdata(handles.lvd_EditFixedInFrameVectorGUI,'frame'); %curElemSet
            bodyInfo = frame.getOriginBody();

            if(isempty(bodyInfo))
                bodyInfo = LvdData.getDefaultInitialBodyInfo(lvdData.celBodyData);
            end
            
            contents = cellstr(get(handles.refFrameTypeCombo,'String'));
            selFrameType = contents{get(handles.refFrameTypeCombo,'Value')};
            refFrameEnum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);

            switch refFrameEnum
                case ReferenceFrameEnum.BodyCenteredInertial
                    newFrame = bodyInfo.getBodyCenteredInertialFrame();

                case ReferenceFrameEnum.BodyFixedRotating
                    newFrame = bodyInfo.getBodyFixedFrame();

                case ReferenceFrameEnum.TwoBodyRotating
                    if(frame.typeEnum == ReferenceFrameEnum.TwoBodyRotating)
                        newFrame = frame;
                    else
                        if(not(isempty(bodyInfo.childrenBodyInfo)))
                            primaryBody = bodyInfo;
                            secondaryBody = bodyInfo.childrenBodyInfo(1);
                        else
                            primaryBody = bodyInfo.getParBodyInfo();
                            secondaryBody = bodyInfo;
                        end

                        originPt = TwoBodyRotatingFrameOriginEnum.Primary;

                        newFrame = TwoBodyRotatingFrame(primaryBody, secondaryBody, originPt, bodyInfo.celBodyData);
                    end

                case ReferenceFrameEnum.UserDefined
                    numFrames = lvdData.geometry.refFrames.getNumRefFrames();
                    if(numFrames >= 1)
                        geometricFrame = lvdData.geometry.refFrames.getRefFrameAtInd(1);
                        newFrame = UserDefinedGeometricFrame(geometricFrame, lvdData);
                    else
                        newFrame = bodyInfo.getBodyCenteredInertialFrame();
                        warndlg('There are no geometric frames available.  A body-centered inertial frame will be selected instead.');
                    end

                otherwise
                    error('Unknown reference frame type: %s', string(refFrameEnum));
            end

            if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
                refFrameEnum = newFrame.typeEnum;
                handles.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
            end

            setappdata(handles.lvd_EditFixedInFrameVectorGUI,'frame',newFrame);
            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', newFrame.getNameStr());
        end

        function populateGUI(app, handles, coordSys)
            set(handles.coordSysNameText,'String',coordSys.getName());
            setappdata(handles.lvd_EditFixedInFrameVectorGUI,'frame',coordSys.frame);

            app.TimeText.Value = fullAccNum2Str(coordSys.time);

            frame = coordSys.frame;
            handles.refFrameTypeCombo.String = ReferenceFrameEnum.getListBoxStr();
            handles.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(frame.typeEnum.name);
            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', frame.getNameStr());
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            if(isempty(strtrim(handles.coordSysNameText.String)))
                errMsg{end+1} = 'Coordinate system name must contain more than white space and must not be empty.';
            end

            value = str2double(app.TimeText.Value);
            enteredStr = app.TimeText.Value;
            numberName = 'Universal Time';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);
        end


        function saveAndClose(app, handles)
            coordSys = getappdata(handles.lvd_EditFixedInFrameVectorGUI, 'coordSys');

            coordSys.setName(handles.coordSysNameText.String);

            coordSys.time = str2double(app.TimeText.Value);

            coordSys.frame = getappdata(handles.lvd_EditFixedInFrameVectorGUI,'frame');

            app.output.output = {true};
            close(handles.lvd_EditFixedInFrameVectorGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditParallelToFrameCoordSysGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditParallelToFrameCoordSysGUI (see VARARGIN)

            % Choose default command line output for lvd_EditParallelToFrameCoordSysGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            coordSys = varargin{1};
            setappdata(hObject, 'coordSys', coordSys);

            lvdData = varargin{2};
            setappdata(hObject, 'lvdData', lvdData);

            app.output = varargin{3};

            populateGUI(app, handles, coordSys);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditParallelToFrameCoordSysGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditFixedInFrameVectorGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditFixedInFrameVectorGUI);
        end

        % Window key press function: lvd_EditFixedInFrameVectorGUI
        function lvd_EditFixedInFrameVectorGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditParallelToFrameCoordSysGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditFixedInFrameVectorGUI);
            end
        end

        % Value changed function: refFrameTypeCombo
        function refFrameTypeCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to refFrameTypeCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns refFrameTypeCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from refFrameTypeCombo
            frameUpdated(app, handles);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
%                 uiresume(handles.lvd_EditFixedInFrameVectorGUI);
                app.saveAndClose(handles);
            else
%                 msgbox(errMsg,'Invalid Coordinate System Inputs','error');
                uialert(app.lvd_EditFixedInFrameVectorGUI, errMsg, 'Invalid Coordinate System Inputs',"Icon","error");
            end
        end

        % Button pushed function: setFrameOptionsButton
        function setFrameOptionsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to setFrameOptionsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            frame = getappdata(handles.lvd_EditFixedInFrameVectorGUI,'frame');

            newFrame = frame.editFrameDialogUI(EditReferenceFrameContextEnum.ForGeometry);

            setappdata(handles.lvd_EditFixedInFrameVectorGUI,'frame',newFrame);

            frameUpdated(app, handles);
        end

        % Value changed function: TimeText
        function TimeTextValueChanged(app, event)
            newInput = app.TimeText.Value;
            newInput = attemptStrEval(newInput);
            app.TimeText.Value = newInput;
        end

        % Menu selected function: EnterUTasDateTimeMenu
        function EnterUTasDateTimeMenuSelected(app, event)
            output2 = AppDesignerGUIOutput({-1});
            enterUTAsDateTimeGUI_App(str2double(app.TimeText.Value), output2);
            secUT = output2.output{1};

            if(secUT >= 0)
                app.TimeText.Value = fullAccNum2Str(secUT);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditFixedInFrameVectorGUI = uifigure('Visible', 'off');
            app.lvd_EditFixedInFrameVectorGUI.Name = 'Edit Coordinate System';
            app.lvd_EditFixedInFrameVectorGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditFixedInFrameVectorGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditFixedInFrameVectorGUI_WindowKeyPressFcn, true);
            app.lvd_EditFixedInFrameVectorGUI.Tag = 'lvd_EditFixedInFrameVectorGUI';
            app.GridLayout = uigridlayout(app.lvd_EditFixedInFrameVectorGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Coordinate System';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Name';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4 = uigridlayout(app.uipanel2);
            app.vectorNameLabel = uilabel(app.GridLayout4);
            app.vectorNameLabel.Tag = 'vectorNameLabel';
            app.vectorNameLabel.Text = 'Coord Sys Name';
            app.coordSysNameText = uieditfield(app.GridLayout4, 'text');
            app.coordSysNameText.Tag = 'coordSysNameText';
            app.coordSysNameText.Value = 'POINT NAME';
            app.vectorNameLabel_2 = uilabel(app.GridLayout4);
            app.vectorNameLabel_2.Tag = 'vectorNameLabel';
            app.vectorNameLabel_2.Text = 'Universal Time';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.text11 = uilabel(app.GridLayout6);
            app.text11.Tag = 'text11';
            app.text11.Text = 'sec';
            app.TimeText = uieditfield(app.GridLayout6, 'text');
            app.TimeText.ValueChangedFcn = createCallbackFcn(app, @TimeTextValueChanged, true);
            app.uipanel3 = uipanel(app.GridLayout3);
            app.uipanel3.Title = 'Parallel To Frame';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout5 = uigridlayout(app.uipanel3);
            app.setFrameOptionsButton = uibutton(app.GridLayout5, 'push');
            app.setFrameOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @setFrameOptionsButton_Callback, true);
            app.setFrameOptionsButton.Tag = 'setFrameOptionsButton';
            app.setFrameOptionsButton.Icon = 'reference_frame.png';
            app.setFrameOptionsButton.Text = 'Set Frame Options';
            app.refFrameTypeCombo = uidropdown(app.GridLayout5);
            app.refFrameTypeCombo.Items = {'Pop-up Menu'};
            app.refFrameTypeCombo.ValueChangedFcn = createCallbackFcn(app, @refFrameTypeCombo_Callback, true);
            app.refFrameTypeCombo.Tag = 'refFrameTypeCombo';
            app.refFrameTypeCombo.Tooltip = 'This is the reference frame with which this frame will be parallel to.  Only the orientation of this reference frame is used.';
            app.refFrameTypeCombo.Value = 'Pop-up Menu';
            app.text22 = uilabel(app.GridLayout5);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Frame';
            app.Image = uiimage(app.GridLayout5);
            app.Image.ImageSource = 'reference_frame.png';
            app.ContextMenu = uicontextmenu(app.lvd_EditFixedInFrameVectorGUI);
            app.EnterUTasDateTimeMenu = uimenu(app.ContextMenu);
            app.EnterUTasDateTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @EnterUTasDateTimeMenuSelected, true);
            app.EnterUTasDateTimeMenu.Text = 'Enter UT as Date/Time';
            % Assign app.ContextMenu
            app.TimeText.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.lvd_EditFixedInFrameVectorGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditParallelToFrameAtTimeCoordSysGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditFixedInFrameVectorGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditParallelToFrameCoordSysGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditFixedInFrameVectorGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditFixedInFrameVectorGUI)
        end
    end
end