classdef lvd_EditGeometricPlanesGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditGeometricPlanesGUI  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        Panel                       matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        GridLayout3                 matlab.ui.container.GridLayout
        removePlaneButton           matlab.ui.control.Button
        addPlaneButton              matlab.ui.control.Button
        planesListBox               matlab.ui.control.ListBox
        planesSearchText            matlab.ui.control.EditField
        closeButton                 matlab.ui.control.Button
        text2                       matlab.ui.control.Label
    end

    methods (Access = private)
        function populateGUI(app, handles, lvdData)
            [~, ~] = app.setListboxStr();
        end
        
        function [listBoxStr, planes] = setListboxStr(app, query)
            if(nargin < 2)
                query = app.planesSearchText.Value;
            end
            
            hFig = app.lvd_EditGeometricPlanesGUI;
            currentValue = app.planesListBox.Value;
            hadSelection = isa(currentValue, 'AbstractGeometricPlane');
            if(hadSelection)
                setappdata(hFig, 'h8Selected', currentValue);
            end
            lvdData = getappdata(app.lvd_EditGeometricPlanesGUI,'lvdData');
            [listBoxStr, planes] = lvdData.geometry.planes.getListboxStr();
            keep = lvd_filterListboxItems(query, listBoxStr);
            listBoxStr = listBoxStr(keep);
            planes = planes(keep);
            storedValue = [];
            if(isappdata(hFig, 'h8Selected'))
                storedValue = getappdata(hFig, 'h8Selected');
            end
            selectedValue = lvd_selectVisibleListValue({currentValue, storedValue}, planes);
            app.planesListBox.ItemsData = planes;
            app.planesListBox.Items = listBoxStr;
            if(not(isempty(selectedValue)))
                app.planesListBox.Value = selectedValue;
                setappdata(hFig, 'h8Selected', selectedValue);
            elseif(hadSelection)
                %A visible selection that was filtered out stays cleared.
            elseif(not(isempty(planes)))
                app.planesListBox.Value = planes(1);
                setappdata(hFig, 'h8Selected', planes(1));
            end
            
            num = numel(planes);
            if(num <= 0)
                app.removePlaneButton.Enable = 'off';
            else
                app.removePlaneButton.Enable = 'on';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditGeometricPlanesGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditGeometricPlanesGUI (see VARARGIN)

            % Choose default command line output for lvd_EditGeometricPlanesGUI
            handles.output = hObject;
            
            centerUIFigure(app.lvd_EditGeometricPlanesGUI);
            applySelectedThemeToApp(app);

            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            populateGUI(app, handles, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditGeometricPlanesGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditGeometricPlanesGUI);
        end

        % Button pushed function: addPlaneButton
        function addPlaneButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addPlaneButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditGeometricPlanesGUI,'lvdData');

            listboxStr = GeometricPlaneEnum.getListBoxStr();
            out = AppDesignerGUIOutput();
            listdlgARH_App('ListString', listboxStr, ...
                'SelectionMode', 'single', ...
                'ListSize', [300 300], ...
                'InitialValue',1, ...
                'Name', 'Select New Plane Type', ...
                'PromptString', 'Select the type of the plane to be created:', ...
                'out',out);
            Selection = out.output{1};
            ok = out.output{2};

            if(ok)
                enum = GeometricPlaneEnum.getEnumForListboxStr(listboxStr{Selection});

                switch enum
                    case GeometricPlaneEnum.PointVectorPlane
                        numVectors = lvdData.geometry.vectors.getNumVectors();
                        numPoints = lvdData.geometry.points.getNumPoints();
                        if(numVectors >= 1 && numPoints >= 1)
                            vector = lvdData.geometry.vectors.getVectorAtInd(1);
                            point = lvdData.geometry.points.getPointAtInd(1);
                            newPlane = PointVectorPlane(point, vector, 'New Plane', lvdData);

                        else
%                             errordlg('Cannot create plane: There must be at least one geometric vector and one geometric point available.','Cannot Create Plane');
                            uialert(app.lvd_EditGeometricPlanesGUI, 'Cannot create plane: There must be at least one geometric vector and one geometric point available.', 'Cannot Create Plane',"Modal",true);
                            return;
                        end

                    case GeometricPlaneEnum.ThreePointPlane
                        numPoints = lvdData.geometry.points.getNumPoints();
                        if(numPoints >= 3)
                            point1 = lvdData.geometry.points.getPointAtInd(1);
                            point2 = lvdData.geometry.points.getPointAtInd(2);
                            point3 = lvdData.geometry.points.getPointAtInd(3);
                            newPlane = ThreePointPlane(point1, point2, point3, 'New Plane', lvdData);

                        else
%                             errordlg('Cannot create plane: There must be at least three geometric points available.','Cannot Create Plane');
                            uialert(app.lvd_EditGeometricPlanesGUI, 'Cannot create plane: There must be at least three geometric points available.', 'Cannot Create Plane',"Modal",true);
                            return;
                        end

                    otherwise
                        error('Unknown Plane Type: %s', enum.name)
                end

                useTF = newPlane.openEditDialog();

                if(useTF)
                    lvdData.geometry.planes.addPlane(newPlane);
                app.planesSearchText.Value = '';
                [~, ~] = app.setListboxStr('');
                app.planesListBox.Value = newPlane;
                end
            end
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to closeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditGeometricPlanesGUI);
        end

        % Window key press function: lvd_EditGeometricPlanesGUI
        function lvd_EditGeometricPlanesGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
 
            % hObject    handle to figure
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            switch(eventdata.Key)
                case 'return'
                    if(app.lvd_EditGeometricPlanesGUI.CurrentObject == app.planesSearchText)
                        return;
                    end
                    close(handles.lvd_EditGeometricPlanesGUI);
                case 'enter'
                    if(app.lvd_EditGeometricPlanesGUI.CurrentObject == app.planesSearchText)
                        return;
                    end
                    close(handles.lvd_EditGeometricPlanesGUI);
                case 'escape'
                    close(handles.lvd_EditGeometricPlanesGUI);
            end
        end

        % Value changed function: planesListBox
        function planesListBox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to planesListBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns planesListBox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from planesListBox
%             lvdData = getappdata(handles.lvd_EditGeometricPlanesGUI,'lvdData');
%             if(strcmpi(get(handles.lvd_EditGeometricPlanesGUI,'SelectionType'),'open') && ...
%                     lvdData.geometry.planes.getNumPlanes() > 0)
%                 selPlane = app.planesListBox.Value;
%                 selPlane.openEditDialog();
% 
%                 populateGUI(app, handles, lvdData);
%             end
        end

        % Button pushed function: removePlaneButton
        function removePlaneButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removePlaneButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditGeometricPlanesGUI,'lvdData');

            oldInd = app.planesListBox.ValueIndex;
            selPlane = app.planesListBox.Value;

            tf = selPlane.isInUse(lvdData);

            if(tf == false)
                lvdData.geometry.planes.removePlane(selPlane);

                lvdData.viewSettings.removeGeoPlaneFromViewProfiles(selPlane);

                [~, visibleObjs] = app.setListboxStr(app.planesSearchText.Value);
                if(not(isempty(visibleObjs)))
                app.planesListBox.Value = visibleObjs(min(oldInd, numel(visibleObjs)));
                end
            else
                msg = sprintf('Could not delete the geometric plane "%s" because it is in use elsewhere.  Remove the plane dependencies before attempting to delete the plane.', selPlane.getName());
                uialert(app.lvd_EditGeometricPlanesGUI, msg, 'Cannot Delete Plane',"Modal",true);
            end
        end

        % Window button down function: lvd_EditGeometricPlanesGUI
        function lvd_EditGeometricPlanesGUIWindowButtonDown(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            selType = app.lvd_EditGeometricPlanesGUI.SelectionType;
            curObj = app.lvd_EditGeometricPlanesGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.planesListBox && numel(app.planesListBox.ItemsData) > 0)
                    selPlane = app.planesListBox.Value;
                    selPlane.openEditDialog();
    
                    [~, ~] = app.setListboxStr();
                end
            end
        end

 
        % Value changing function: planesSearchText
        function planesSearchTextValueChanging(app, event)
            [~, ~] = app.setListboxStr(event.Value);
        end
        % Value changed function: planesSearchText
        function planesSearchTextValueChanged(app, event)
            [~, ~] = app.setListboxStr(event.Value);
        end
    end
    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditGeometricPlanesGUI = uifigure('Visible', 'off');
            app.lvd_EditGeometricPlanesGUI.Name = 'Edit Planes';
            app.lvd_EditGeometricPlanesGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditGeometricPlanesGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_EditGeometricPlanesGUIWindowButtonDown, true);
            app.lvd_EditGeometricPlanesGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditGeometricPlanesGUI_WindowKeyPressFcn, true);
            app.lvd_EditGeometricPlanesGUI.Tag = 'lvd_EditGeometricPlanesGUI';
            app.GridLayout = uigridlayout(app.lvd_EditGeometricPlanesGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Planes';
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Icon = 'save-file.png';
            app.closeButton.Text = 'Close';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.planesSearchText = uieditfield(app.GridLayout2, 'text');
            app.planesSearchText.ValueChangingFcn = createCallbackFcn(app, @planesSearchTextValueChanging, true);
            app.planesSearchText.ValueChangedFcn = createCallbackFcn(app, @planesSearchTextValueChanged, true);
            app.planesSearchText.Tag = 'planesSearchText';
            app.planesSearchText.Tooltip = 'Type to filter the planes below.';
            app.planesSearchText.Placeholder = 'Search planes...';
            app.planesListBox = uilistbox(app.GridLayout2);
            app.planesListBox.Items = {'Listbox'};
            app.planesListBox.ValueChangedFcn = createCallbackFcn(app, @planesListBox_Callback, true);
            app.planesListBox.Tag = 'planesListBox';
            app.planesListBox.Tooltip = 'Double click an entry to edit it.';
            app.planesListBox.Value = 'Listbox';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.addPlaneButton = uibutton(app.GridLayout3, 'push');
            app.addPlaneButton.ButtonPushedFcn = createCallbackFcn(app, @addPlaneButton_Callback, true);
            app.addPlaneButton.Tag = 'addPlaneButton';
            app.addPlaneButton.Icon = 'add.png';
            app.addPlaneButton.Text = 'Add Plane';
            app.removePlaneButton = uibutton(app.GridLayout3, 'push');
            app.removePlaneButton.ButtonPushedFcn = createCallbackFcn(app, @removePlaneButton_Callback, true);
            app.removePlaneButton.Tag = 'removePlaneButton';
            app.removePlaneButton.Icon = 'remove.png';
            app.removePlaneButton.Text = 'Remove Plane';
            % Show the figure after all components are created
            app.lvd_EditGeometricPlanesGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditGeometricPlanesGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditGeometricPlanesGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditGeometricPlanesGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditGeometricPlanesGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditGeometricPlanesGUI)
        end
    end
end