classdef lvd_EditThreePointPlaneGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditThreePointPlaneGUI  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        uipanel1                    matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        uipanel5                    matlab.ui.container.Panel
        GridLayout6                 matlab.ui.container.GridLayout
        Image2                      matlab.ui.control.Image
        Image                       matlab.ui.control.Image
        text34                      matlab.ui.control.Label
        text33                      matlab.ui.control.Label
        alphaText                   matlab.ui.control.EditField
        edgeLengthText              matlab.ui.control.EditField
        lineSpecCombo               matlab.ui.control.DropDown
        lineColorCombo              matlab.ui.control.DropDown
        text32                      matlab.ui.control.Label
        text30                      matlab.ui.control.Label
        uipanel3                    matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        Image5                      matlab.ui.control.Image
        Image4                      matlab.ui.control.Image
        Image3                      matlab.ui.control.Image
        point3Combo                 matlab.ui.control.DropDown
        point2Combo                 matlab.ui.control.DropDown
        point1Combo                 matlab.ui.control.DropDown
        text35                      matlab.ui.control.Label
        text31                      matlab.ui.control.Label
        text5                       matlab.ui.control.Label
        uipanel2                    matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        nameText                    matlab.ui.control.EditField
        stageName                   matlab.ui.control.Label
        GridLayout2                 matlab.ui.container.GridLayout
        cancelButton                matlab.ui.control.Button
        saveAndCloseButton          matlab.ui.control.Button
        text2                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput
    end
    
    methods (Access = private)
        function populateGUI(app, handles, lvdData, plane)
                set(handles.nameText,'String',plane.getName());
            
                pointsListBoxStr = lvdData.geometry.points.getListboxStr();
            
                handles.point1Combo.String = pointsListBoxStr;
                handles.point1Combo.Value = lvdData.geometry.points.getIndsForPoints(plane.point1);
            
                handles.point2Combo.String = pointsListBoxStr;
                handles.point2Combo.Value = lvdData.geometry.points.getIndsForPoints(plane.point2);
            
                handles.point3Combo.String = pointsListBoxStr;
                handles.point3Combo.Value = lvdData.geometry.points.getIndsForPoints(plane.point3);
            
                lvdSetupColorPicker(app.lvd_EditThreePointPlaneGUI, app.GridLayout6, 2, 2, handles.lineColorCombo, lvd_colorSpecToRGB(plane.lineColor), 'The color of the plane''s edge line and face on the display.', 'lineColor');
            
                handles.lineSpecCombo.String = LineSpecEnum.getListboxStr();
                handles.lineSpecCombo.Value = LineSpecEnum.getIndForName(plane.lineSpec.name);
            
                handles.edgeLengthText.String = fullAccNum2Str(plane.edgeLength);
                handles.alphaText.String = fullAccNum2Str(plane.alpha*100);
        end
        
        function errMsg = validateInputs(app, handles)
                errMsg = {};
            
                if(handles.point1Combo.Value == handles.point2Combo.Value || ...
                   handles.point1Combo.Value == handles.point3Combo.Value || ...
                   handles.point2Combo.Value == handles.point3Combo.Value)
                    errMsg{end+1} = 'All three points on the plane must be unique.';
                end
            
                edgeLength = str2double(get(handles.edgeLengthText,'String'));
                enteredStr = get(handles.edgeLengthText,'String');
                numberName = 'Edge Length';
                lb = 0;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(edgeLength, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                alpha = str2double(get(handles.alphaText,'String'));
                enteredStr = get(handles.alphaText,'String');
                numberName = 'Face Transparency';
                lb = 0;
                ub = 100;
                isInt = false;
                errMsg = validateNumber(alpha, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                if(isempty(strtrim(handles.nameText.String)))
                    errMsg{end+1} = 'Plane name must contain more than white space and must not be empty.';
                end
        end
        
        
        function saveAndClose(app, handles)
        lvdData = getappdata(handles.lvd_EditThreePointPlaneGUI, 'lvdData');
        plane = getappdata(handles.lvd_EditThreePointPlaneGUI, 'plane');
        
        plane.setName(handles.nameText.String);
        
        plane.point1 = lvdData.geometry.points.getPointAtInd(handles.point1Combo.Value);
        plane.point2 = lvdData.geometry.points.getPointAtInd(handles.point2Combo.Value);
        plane.point3 = lvdData.geometry.points.getPointAtInd(handles.point3Combo.Value);
        
        plane.lineColor = lvdGetColorPickerRGB(app.lvd_EditThreePointPlaneGUI, 'lineColor', plane.lineColor);
        
        str = handles.lineSpecCombo.String{handles.lineSpecCombo.Value};
        plane.lineSpec = LineSpecEnum.getEnumForListboxStr(str);
        
        plane.edgeLength = str2double(handles.edgeLengthText.String);
        plane.alpha = str2double(handles.alphaText.String)/100;
        
        app.output.output = {true};
        close(handles.lvd_EditThreePointPlaneGUI);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditThreePointPlaneGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
                % This function has no output args, see OutputFcn.
                % hObject    handle to figure
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
                % varargin   command line arguments to lvd_EditThreePointPlaneGUI (see VARARGIN)
            
                % Choose default command line output for lvd_EditThreePointPlaneGUI
                handles.output = hObject;
            
                centerUIFigure(hObject);
                applySelectedThemeToApp(app);
            
                plane = varargin{1};
                setappdata(hObject, 'plane', plane);
            
                lvdData = varargin{2};
                setappdata(hObject, 'lvdData', lvdData);
                
                app.output = varargin{3};
            
            	populateGUI(app, handles, lvdData, plane);
            
                % Update handles structure
                guidata(hObject, handles);
            
                % UIWAIT makes lvd_EditThreePointPlaneGUI wait for user response (see UIRESUME)
                uiwait(handles.lvd_EditThreePointPlaneGUI);
        end

        % Value changed function: alphaText
        function alphaText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to alphaText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of alphaText as text
            %        str2double(get(hObject,'String')) returns contents of alphaText as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                close(handles.lvd_EditThreePointPlaneGUI);
        end

        % Value changed function: edgeLengthText
        function edgeLengthText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edgeLengthText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edgeLengthText as text
            %        str2double(get(hObject,'String')) returns contents of edgeLengthText as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
        end

        % Window key press function: lvd_EditThreePointPlaneGUI
        function lvd_EditThreePointPlaneGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to lvd_EditThreePointPlaneGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
                switch(eventdata.Key)
                    case 'return'
                        saveAndCloseButton_Callback(app, evt);
                    case 'enter'
                        saveAndCloseButton_Callback(app, evt);
                    case 'escape'
                        close(handles.lvd_EditThreePointPlaneGUI);
                end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                errMsg = validateInputs(app, handles);
            
                if(isempty(errMsg))
%                     uiresume(handles.lvd_EditThreePointPlaneGUI);
                    app.saveAndClose(handles);
                else
%                     msgbox(errMsg,'Invalid Plane Inputs','error');
                    uialert(app.lvd_EditThreePointPlaneGUI, errMsg, 'Invalid Plane Inputs', "Icon","error");
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditThreePointPlaneGUI = uifigure('Visible', 'off');
            app.lvd_EditThreePointPlaneGUI.Name = 'Edit Plane';
            app.lvd_EditThreePointPlaneGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditThreePointPlaneGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditThreePointPlaneGUI_WindowKeyPressFcn, true);
            app.lvd_EditThreePointPlaneGUI.Tag = 'lvd_EditThreePointPlaneGUI';
            app.GridLayout = uigridlayout(app.lvd_EditThreePointPlaneGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Plane';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Name';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4 = uigridlayout(app.uipanel2);
            app.stageName = uilabel(app.GridLayout4);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Plane Name';
            app.nameText = uieditfield(app.GridLayout4, 'text');
            app.nameText.Tag = 'nameText';
            app.nameText.Value = 'POINT NAME';
            app.uipanel3 = uipanel(app.GridLayout3);
            app.uipanel3.Title = 'Vector';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout5 = uigridlayout(app.uipanel3);
            app.text5 = uilabel(app.GridLayout5);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Origin Point';
            app.text31 = uilabel(app.GridLayout5);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Point 2';
            app.text35 = uilabel(app.GridLayout5);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Point 3';
            app.point1Combo = uidropdown(app.GridLayout5);
            app.point1Combo.Items = {'Pop-up Menu'};
            app.point1Combo.Tag = 'point1Combo';
            app.point1Combo.Tooltip = 'This is the first point in the plane and the center of the plane when displayed.';
            app.point1Combo.Value = 'Pop-up Menu';
            app.point2Combo = uidropdown(app.GridLayout5);
            app.point2Combo.Items = {'Pop-up Menu'};
            app.point2Combo.Tag = 'point2Combo';
            app.point2Combo.Tooltip = 'This is the second point in the plane.';
            app.point2Combo.Value = 'Pop-up Menu';
            app.point3Combo = uidropdown(app.GridLayout5);
            app.point3Combo.Items = {'Pop-up Menu'};
            app.point3Combo.Tag = 'point3Combo';
            app.point3Combo.Tooltip = 'This is the third point in the plane.';
            app.point3Combo.Value = 'Pop-up Menu';
            app.Image3 = uiimage(app.GridLayout5);
            app.Image3.ImageSource = 'circle(1).png';
            app.Image4 = uiimage(app.GridLayout5);
            app.Image4.ImageSource = 'circle_3.png';
            app.Image5 = uiimage(app.GridLayout5);
            app.Image5.ImageSource = 'circle_2.png';
            app.uipanel5 = uipanel(app.GridLayout3);
            app.uipanel5.Title = 'Display';
            app.uipanel5.Tag = 'uipanel5';
            app.GridLayout6 = uigridlayout(app.uipanel5);
            app.text30 = uilabel(app.GridLayout6);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Plane Edge';
            app.text32 = uilabel(app.GridLayout6);
            app.text32.Tag = 'text32';
            app.text32.Text = 'Plane Face';
            app.lineColorCombo = uidropdown(app.GridLayout6);
            app.lineColorCombo.Items = {'Pop-up Menu'};
            app.lineColorCombo.Tag = 'lineColorCombo';
            app.lineColorCombo.Tooltip = 'The color of the plane''s edge line and face on the display.';
            app.lineColorCombo.Value = 'Pop-up Menu';
            app.lineSpecCombo = uidropdown(app.GridLayout6);
            app.lineSpecCombo.Items = {'Pop-up Menu'};
            app.lineSpecCombo.Tag = 'lineSpecCombo';
            app.lineSpecCombo.Tooltip = 'The style of the plane''s edge line on the display.';
            app.lineSpecCombo.Value = 'Pop-up Menu';
            app.edgeLengthText = uieditfield(app.GridLayout6, 'text');
            app.edgeLengthText.ValueChangedFcn = createCallbackFcn(app, @edgeLengthText_Callback, true);
            app.edgeLengthText.Tag = 'edgeLengthText';
            app.edgeLengthText.Tooltip = 'The length of the plane edge when displayed.';
            app.edgeLengthText.Value = 'Pop-up Menu';
            app.alphaText = uieditfield(app.GridLayout6, 'text');
            app.alphaText.ValueChangedFcn = createCallbackFcn(app, @alphaText_Callback, true);
            app.alphaText.Tag = 'alphaText';
            app.alphaText.Tooltip = 'The transparency of the plane face when displayed.';
            app.alphaText.Value = 'Pop-up Menu';
            app.text33 = uilabel(app.GridLayout6);
            app.text33.Tag = 'text33';
            app.text33.Text = 'km';
            app.text34 = uilabel(app.GridLayout6);
            app.text34.Tag = 'text34';
            app.text34.Text = '%';
            app.Image = uiimage(app.GridLayout6);
            app.Image.ImageSource = 'color_wheel.png';
            app.Image2 = uiimage(app.GridLayout6);
            app.Image2.ImageSource = 'line.png';
            % Show the figure after all components are created
            app.lvd_EditThreePointPlaneGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditThreePointPlaneGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditThreePointPlaneGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditThreePointPlaneGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditThreePointPlaneGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditThreePointPlaneGUI)
        end
    end
end