classdef lvd_EditCelestialBodyPointGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditCelestialBodyPointGUI  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        uipanel1                       matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        uipanel3                       matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
        celestialBodyCombo             matlab.ui.control.DropDown
        text5                          matlab.ui.control.Label
        uipanel2                       matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        pointNameText                  matlab.ui.control.EditField
        stageName                      matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        cancelButton                   matlab.ui.control.Button
        saveAndCloseButton             matlab.ui.control.Button
        text2                          matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, point)
            set(handles.pointNameText,'String',point.getName());

            celBodyData = point.bodyInfo.celBodyData;
            populateBodiesCombo(celBodyData, handles.celestialBodyCombo, false);

            [~, sortedBodyInfo] = ma_getSortedBodyNames(celBodyData);
            sortedBodyInfo = [sortedBodyInfo{:}];

            ind = find(point.bodyInfo == sortedBodyInfo,1,'first');
            handles.celestialBodyCombo.Value = ind;
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            if(isempty(strtrim(handles.pointNameText.String)))
                errMsg = {'Point name must contain more than white space and must not be empty.'};
            end
        end

        function saveAndClose(app, handles)
            point = getappdata(handles.lvd_EditCelestialBodyPointGUI, 'point');

            point.setName(handles.pointNameText.String);

            celBodyData = point.bodyInfo.celBodyData;
            [~, sortedBodyInfo] = ma_getSortedBodyNames(celBodyData);
            sortedBodyInfo = [sortedBodyInfo{:}];
            point.bodyInfo = sortedBodyInfo(handles.celestialBodyCombo.Value);

            app.output.output = {true};
            close(handles.lvd_EditCelestialBodyPointGUI);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditCelestialBodyPointGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditCelestialBodyPointGUI (see VARARGIN)

            % Choose default command line output for lvd_EditCelestialBodyPointGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            point = varargin{1};
            setappdata(hObject, 'point', point);
            
            app.output = varargin{2};

            populateGUI(app, handles, point);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditCelestialBodyPointGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditCelestialBodyPointGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditCelestialBodyPointGUI);
        end

        % Window key press function: lvd_EditCelestialBodyPointGUI
        function lvd_EditCelestialBodyPointGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditCelestialBodyPointGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditCelestialBodyPointGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                %                     uiresume(handles.lvd_EditCelestialBodyPointGUI);
                app.saveAndClose(handles)
            else
                %                     msgbox(errMsg,'Invalid Point Inputs','error');
                uialert(app.lvd_EditCelestialBodyPointGUI, errMsg, 'Invalid Point Inputs', "Icon", "error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditCelestialBodyPointGUI = uifigure('Visible', 'off');
            app.lvd_EditCelestialBodyPointGUI.Name = 'Edit Point';
            app.lvd_EditCelestialBodyPointGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditCelestialBodyPointGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditCelestialBodyPointGUI_WindowKeyPressFcn, true);
            app.lvd_EditCelestialBodyPointGUI.Tag = 'lvd_EditCelestialBodyPointGUI';
            app.GridLayout = uigridlayout(app.lvd_EditCelestialBodyPointGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Point';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Name';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4 = uigridlayout(app.uipanel2);
            app.stageName = uilabel(app.GridLayout4);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Point Name';
            app.pointNameText = uieditfield(app.GridLayout4, 'text');
            app.pointNameText.Tag = 'pointNameText';
            app.pointNameText.Value = 'POINT NAME';
            app.uipanel3 = uipanel(app.GridLayout3);
            app.uipanel3.Title = 'Location';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout5 = uigridlayout(app.uipanel3);
            app.text5 = uilabel(app.GridLayout5);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Celestial Body';
            app.celestialBodyCombo = uidropdown(app.GridLayout5);
            app.celestialBodyCombo.Items = {'Pop-up Menu'};
            app.celestialBodyCombo.Tag = 'celestialBodyCombo';
            app.celestialBodyCombo.Tooltip = 'This point will be located at the center of the selected celestial body.';
            app.celestialBodyCombo.Value = 'Pop-up Menu';
            app.Image = uiimage(app.GridLayout5);
            app.Image.ImageSource = 'jupiter.png';
            % Show the figure after all components are created
            app.lvd_EditCelestialBodyPointGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditCelestialBodyPointGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditCelestialBodyPointGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditCelestialBodyPointGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditCelestialBodyPointGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditCelestialBodyPointGUI)
        end
    end
end