classdef lvd_EditFixedInFramePointGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditFixedInFramePointGUI  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        uipanel1                      matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        uipanel5                      matlab.ui.container.Panel
        GridLayout9                   matlab.ui.container.GridLayout
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        dispTrajCheckbox              matlab.ui.control.CheckBox
        pointLineSpecCombo            matlab.ui.control.DropDown
        pointLineColorCombo           matlab.ui.control.DropDown
        text30                        matlab.ui.control.Label
        pointMarkerShapeCombo         matlab.ui.control.DropDown
        pointColorCombo               matlab.ui.control.DropDown
        text29                        matlab.ui.control.Label
        uipanel3                      matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        GridLayout8                   matlab.ui.container.GridLayout
        text21                        matlab.ui.control.Label
        text19                        matlab.ui.control.Label
        text6                         matlab.ui.control.Label
        zCoordText                    matlab.ui.control.EditField
        yCoordText                    matlab.ui.control.EditField
        xCoordText                    matlab.ui.control.EditField
        GridLayout7                   matlab.ui.container.GridLayout
        text20                        matlab.ui.control.Label
        text18                        matlab.ui.control.Label
        text5                         matlab.ui.control.Label
        GridLayout6                   matlab.ui.container.GridLayout
        refFrameTypeCombo             matlab.ui.control.DropDown
        setFrameOptionsButton         matlab.ui.control.Button
        text22                        matlab.ui.control.Label
        uipanel2                      matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        pointNameText                 matlab.ui.control.EditField
        stageName                     matlab.ui.control.Label
        GridLayout2                   matlab.ui.container.GridLayout
        cancelButton                  matlab.ui.control.Button
        saveAndCloseButton            matlab.ui.control.Button
        text2                         matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function frameUpdated(app, handles)
            lvdData = getappdata(handles.lvd_EditFixedInFramePointGUI, 'lvdData');

            curElemSet = getappdata(handles.lvd_EditFixedInFramePointGUI,'curElemSet');
            bodyInfo = curElemSet.frame.getOriginBody();

            contents = cellstr(get(handles.refFrameTypeCombo,'String'));
            selFrameType = contents{get(handles.refFrameTypeCombo,'Value')};
            refFrameEnum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);

            switch refFrameEnum
                case ReferenceFrameEnum.BodyCenteredInertial
                    newFrame = bodyInfo.getBodyCenteredInertialFrame();

                case ReferenceFrameEnum.BodyFixedRotating
                    newFrame = bodyInfo.getBodyFixedFrame();

                case ReferenceFrameEnum.TwoBodyRotating
                    if(curElemSet.frame.typeEnum == ReferenceFrameEnum.TwoBodyRotating)
                        newFrame = curElemSet.frame;
                    else
                        if(not(isempty(bodyInfo.childrenBodyInfo)))
                            primaryBody = bodyInfo;
                            secondaryBody = bodyInfo.childrenBodyInfo(1);
                        else
                            primaryBody = bodyInfo.getParBodyInfo();
                            secondaryBody = bodyInfo;
                        end

                        originPt = TwoBodyRotatingFrameOriginEnum.Primary;

                        newFrame = TwoBodyRotatingFrame(primaryBody, secondaryBody, originPt, bodyInfo.celBodyData);
                    end

                case ReferenceFrameEnum.UserDefined
                    numFrames = lvdData.geometry.refFrames.getNumRefFrames();
                    if(numFrames >= 1)
                        geometricFrame = lvdData.geometry.refFrames.getRefFrameAtInd(1);
                        newFrame = UserDefinedGeometricFrame(geometricFrame, lvdData);
                    else
                        newFrame = bodyInfo.getBodyCenteredInertialFrame();
                        warndlg('There are no geometric frames available.  A body-centered inertial frame will be selected instead.');
                    end

                otherwise
                    error('Unknown reference frame type: %s', string(refFrameEnum));
            end

            if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
                refFrameEnum = newFrame.typeEnum;
                handles.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
            end

            curElemSet.frame = newFrame;
            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', newFrame.getNameStr());
        end

        function populateGUI(app, handles, point)
            set(handles.pointNameText,'String',point.getName());
            setappdata(handles.lvd_EditFixedInFramePointGUI,'curElemSet',point.cartElem);

            frame = point.getFrame();
            handles.refFrameTypeCombo.String = ReferenceFrameEnum.getListBoxStr();
            handles.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(frame.typeEnum.name);
            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', frame.getNameStr());

            rVect = point.getRVect();
            set(handles.xCoordText,'String',fullAccNum2Str(rVect(1)));
            set(handles.yCoordText,'String',fullAccNum2Str(rVect(2)));
            set(handles.zCoordText,'String',fullAccNum2Str(rVect(3)));

            lvdSetupColorPicker(app.lvd_EditFixedInFramePointGUI, app.GridLayout9, 2, 2, handles.pointColorCombo, lvd_colorSpecToRGB(point.markerColor), 'The color of the point marker on the display.', 'markerColor');

            handles.pointMarkerShapeCombo.String = MarkerStyleEnum.getListboxStr();
            handles.pointMarkerShapeCombo.Value = MarkerStyleEnum.getIndForName(point.markerShape.name);

            lvdSetupColorPicker(app.lvd_EditFixedInFramePointGUI, app.GridLayout9, 2, 3, handles.pointLineColorCombo, lvd_colorSpecToRGB(point.trkLineColor), 'The color of the point track line on the display.', 'trkLineColor');

            handles.pointLineSpecCombo.String = LineSpecEnum.getListboxStr();
            handles.pointLineSpecCombo.Value = LineSpecEnum.getIndForName(point.trkLineSpec.name);

            handles.dispTrajCheckbox.Value = double(point.plotTrkLine);
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            val = str2double(get(handles.xCoordText,'String'));
            enteredStr = get(handles.xCoordText,'String');
            numberName = 'X Coordinate';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);

            val = str2double(get(handles.yCoordText,'String'));
            enteredStr = get(handles.yCoordText,'String');
            numberName = 'Y Coordinate';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);

            val = str2double(get(handles.zCoordText,'String'));
            enteredStr = get(handles.zCoordText,'String');
            numberName = 'Z Coordinate';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(strtrim(handles.pointNameText.String)))
                errMsg{end+1} = {'Point name must contain more than white space and must not be empty.'};
            end
        end


        function saveAndClose(app, handles)
            point = getappdata(handles.lvd_EditFixedInFramePointGUI, 'point');

            point.setName(handles.pointNameText.String);

            x = str2double(get(handles.xCoordText,'String'));
            y = str2double(get(handles.yCoordText,'String'));
            z = str2double(get(handles.zCoordText,'String'));
            point.setRVect([x;y;z]);

            point.markerColor = lvdGetColorPickerRGB(app.lvd_EditFixedInFramePointGUI, 'markerColor', point.markerColor);

            str = handles.pointMarkerShapeCombo.String{handles.pointMarkerShapeCombo.Value};
            point.markerShape = MarkerStyleEnum.getEnumForListboxStr(str);

            point.trkLineColor = lvdGetColorPickerRGB(app.lvd_EditFixedInFramePointGUI, 'trkLineColor', point.trkLineColor);

            str = handles.pointLineSpecCombo.String{handles.pointLineSpecCombo.Value};
            point.trkLineSpec = LineSpecEnum.getEnumForListboxStr(str);

            point.plotTrkLine = logical(handles.dispTrajCheckbox.Value);

            app.output.output = {true};
            close(handles.lvd_EditFixedInFramePointGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditFixedInFramePointGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditFixedInFramePointGUI (see VARARGIN)

            % Choose default command line output for lvd_EditFixedInFramePointGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            point = varargin{1};
            setappdata(hObject, 'point', point);

            lvdData = varargin{2};
            setappdata(hObject, 'lvdData', lvdData);

            app.output = varargin{3};

            populateGUI(app, handles, point);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditFixedInFramePointGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditFixedInFramePointGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditFixedInFramePointGUI);
        end

        % Window key press function: lvd_EditFixedInFramePointGUI
        function lvd_EditFixedInFramePointGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditFixedInFramePointGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditFixedInFramePointGUI);
            end
        end

        % Value changed function: refFrameTypeCombo
        function refFrameTypeCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to refFrameTypeCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns refFrameTypeCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from refFrameTypeCombo
            frameUpdated(app, handles);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                %                 uiresume(handles.lvd_EditFixedInFramePointGUI);
                app.saveAndClose(handles);
            else
                %                 msgbox(errMsg,'Invalid Point Inputs','error');
                uialert(app.lvd_EditFixedInFramePointGUI, errMsg, 'Invalid Point Inputs', "Icon","error");
            end
        end

        % Button pushed function: setFrameOptionsButton
        function setFrameOptionsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to setFrameOptionsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            curElemSet = getappdata(handles.lvd_EditFixedInFramePointGUI,'curElemSet');

            frame = curElemSet.frame;
            newFrame = frame.editFrameDialogUI(EditReferenceFrameContextEnum.ForGeometry);
            curElemSet.frame = newFrame;

            setappdata(handles.lvd_EditFixedInFramePointGUI,'curElemSet',curElemSet);

            frameUpdated(app, handles);
        end

        % Value changed function: xCoordText
        function xCoordText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to xCoordText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of xCoordText as text
            %        str2double(get(hObject,'String')) returns contents of xCoordText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: yCoordText
        function yCoordText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to yCoordText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of yCoordText as text
            %        str2double(get(hObject,'String')) returns contents of yCoordText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: zCoordText
        function zCoordText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to zCoordText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of zCoordText as text
            %        str2double(get(hObject,'String')) returns contents of zCoordText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditFixedInFramePointGUI = uifigure('Visible', 'off');
            app.lvd_EditFixedInFramePointGUI.Name = 'Edit Point';
            app.lvd_EditFixedInFramePointGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditFixedInFramePointGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditFixedInFramePointGUI_WindowKeyPressFcn, true);
            app.lvd_EditFixedInFramePointGUI.Tag = 'lvd_EditFixedInFramePointGUI';
            app.GridLayout = uigridlayout(app.lvd_EditFixedInFramePointGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Point';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Name';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4 = uigridlayout(app.uipanel2);
            app.stageName = uilabel(app.GridLayout4);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Point Name';
            app.pointNameText = uieditfield(app.GridLayout4, 'text');
            app.pointNameText.Tag = 'pointNameText';
            app.pointNameText.Value = 'POINT NAME';
            app.uipanel3 = uipanel(app.GridLayout3);
            app.uipanel3.Title = 'Location';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout5 = uigridlayout(app.uipanel3);
            app.text22 = uilabel(app.GridLayout5);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Frame Type';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.setFrameOptionsButton = uibutton(app.GridLayout6, 'push');
            app.setFrameOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @setFrameOptionsButton_Callback, true);
            app.setFrameOptionsButton.Tag = 'setFrameOptionsButton';
            app.setFrameOptionsButton.Icon = 'reference_frame.png';
            app.setFrameOptionsButton.Text = 'Set Frame Options';
            app.refFrameTypeCombo = uidropdown(app.GridLayout6);
            app.refFrameTypeCombo.Items = {'Pop-up Menu'};
            app.refFrameTypeCombo.ValueChangedFcn = createCallbackFcn(app, @refFrameTypeCombo_Callback, true);
            app.refFrameTypeCombo.Tag = 'refFrameTypeCombo';
            app.refFrameTypeCombo.Tooltip = 'The reference frame with which the defined point is relative to.';
            app.refFrameTypeCombo.Value = 'Pop-up Menu';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.text5 = uilabel(app.GridLayout7);
            app.text5.Tag = 'text5';
            app.text5.Text = 'X Coordinate';
            app.text18 = uilabel(app.GridLayout7);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Y Coordinate';
            app.text20 = uilabel(app.GridLayout7);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Z Coordinate';
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.xCoordText = uieditfield(app.GridLayout8, 'text');
            app.xCoordText.ValueChangedFcn = createCallbackFcn(app, @xCoordText_Callback, true);
            app.xCoordText.Tag = 'xCoordText';
            app.xCoordText.Tooltip = 'The x coordinate of the location of this point with respect to the selected reference frame.';
            app.xCoordText.Value = '1';
            app.yCoordText = uieditfield(app.GridLayout8, 'text');
            app.yCoordText.ValueChangedFcn = createCallbackFcn(app, @yCoordText_Callback, true);
            app.yCoordText.Tag = 'yCoordText';
            app.yCoordText.Tooltip = 'The y coordinate of the location of this point with respect to the selected reference frame.';
            app.yCoordText.Value = '1';
            app.zCoordText = uieditfield(app.GridLayout8, 'text');
            app.zCoordText.ValueChangedFcn = createCallbackFcn(app, @zCoordText_Callback, true);
            app.zCoordText.Tag = 'zCoordText';
            app.zCoordText.Tooltip = 'The z coordinate of the location of this point with respect to the selected reference frame.';
            app.zCoordText.Value = '1';
            app.text6 = uilabel(app.GridLayout8);
            app.text6.Tag = 'text6';
            app.text6.Text = 'km';
            app.text19 = uilabel(app.GridLayout8);
            app.text19.Tag = 'text19';
            app.text19.Text = 'km';
            app.text21 = uilabel(app.GridLayout8);
            app.text21.Tag = 'text21';
            app.text21.Text = 'km';
            app.uipanel5 = uipanel(app.GridLayout3);
            app.uipanel5.Title = 'Display';
            app.uipanel5.Tag = 'uipanel5';
            app.GridLayout9 = uigridlayout(app.uipanel5);
            app.text29 = uilabel(app.GridLayout9);
            app.text29.Tag = 'text29';
            app.text29.Text = 'Point Marker';
            app.pointColorCombo = uidropdown(app.GridLayout9);
            app.pointColorCombo.Items = {'Pop-up Menu'};
            app.pointColorCombo.Tag = 'pointColorCombo';
            app.pointColorCombo.Tooltip = 'The color of the point marker on the display.';
            app.pointColorCombo.Value = 'Pop-up Menu';
            app.pointMarkerShapeCombo = uidropdown(app.GridLayout9);
            app.pointMarkerShapeCombo.Items = {'Pop-up Menu'};
            app.pointMarkerShapeCombo.Tag = 'pointMarkerShapeCombo';
            app.pointMarkerShapeCombo.Tooltip = 'The shape of the point marker on the display.';
            app.pointMarkerShapeCombo.Value = 'Pop-up Menu';
            app.text30 = uilabel(app.GridLayout9);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Point Trajectory';
            app.pointLineColorCombo = uidropdown(app.GridLayout9);
            app.pointLineColorCombo.Items = {'Pop-up Menu'};
            app.pointLineColorCombo.Tag = 'pointLineColorCombo';
            app.pointLineColorCombo.Tooltip = 'The color of the point track line on the display.';
            app.pointLineColorCombo.Value = 'Pop-up Menu';
            app.pointLineSpecCombo = uidropdown(app.GridLayout9);
            app.pointLineSpecCombo.Items = {'Pop-up Menu'};
            app.pointLineSpecCombo.Tag = 'pointLineSpecCombo';
            app.pointLineSpecCombo.Tooltip = 'The style of the line of the point track line on the display.';
            app.pointLineSpecCombo.Value = 'Pop-up Menu';
            app.dispTrajCheckbox = uicheckbox(app.GridLayout9);
            app.dispTrajCheckbox.Tag = 'dispTrajCheckbox';
            app.dispTrajCheckbox.Text = 'Display Trajectory';
            app.Image = uiimage(app.GridLayout9);
            app.Image.ImageSource = 'color_wheel.png';
            app.Image2 = uiimage(app.GridLayout9);
            app.Image2.ImageSource = 'line.png';
            % Show the figure after all components are created
            app.lvd_EditFixedInFramePointGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditFixedInFramePointGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditFixedInFramePointGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditFixedInFramePointGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditFixedInFramePointGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditFixedInFramePointGUI)
        end
    end
end