classdef lvd_EditGeometricPointsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditGeometricPointsGUI  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout2                 matlab.ui.container.GridLayout
        GridLayout3                 matlab.ui.container.GridLayout
        removePointButton           matlab.ui.control.Button
        addPointButton              matlab.ui.control.Button
        pointsListBox               matlab.ui.control.ListBox
        pointsSearchText            matlab.ui.control.EditField
        closeButton                 matlab.ui.control.Button
        text2                       matlab.ui.control.Label
    end

    methods (Access = private)
        function populateGUI(app, handles, lvdData)
            [~, ~] = app.setListboxStr();
        end
        
        function [listBoxStr, points] = setListboxStr(app, query)
            if(nargin < 2)
                query = app.pointsSearchText.Value;
            end
            
            hFig = app.lvd_EditGeometricPointsGUI;
            currentValue = app.pointsListBox.Value;
            hadSelection = isa(currentValue, 'AbstractGeometricPoint');
            if(hadSelection)
                setappdata(hFig, 'h8Selected', currentValue);
            end
            lvdData = getappdata(app.lvd_EditGeometricPointsGUI,'lvdData');
            [listBoxStr, points] = lvdData.geometry.points.getListboxStr();
            keep = lvd_filterListboxItems(query, listBoxStr);
            listBoxStr = listBoxStr(keep);
            points = points(keep);
            storedValue = [];
            if(isappdata(hFig, 'h8Selected'))
                storedValue = getappdata(hFig, 'h8Selected');
            end
            selectedValue = lvd_selectVisibleListValue({currentValue, storedValue}, points);
            app.pointsListBox.ItemsData = points;
            app.pointsListBox.Items = listBoxStr;
            if(not(isempty(selectedValue)))
                app.pointsListBox.Value = selectedValue;
                setappdata(hFig, 'h8Selected', selectedValue);
            elseif(hadSelection)
                %A visible selection that was filtered out stays cleared.
            elseif(not(isempty(points)))
                app.pointsListBox.Value = points(1);
                setappdata(hFig, 'h8Selected', points(1));
            end
            
            num = numel(points);
            if(num <= 0)
                app.removePointButton.Enable = 'off';
            else
                app.removePointButton.Enable = 'on';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditGeometricPointsGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditGeometricPointsGUI (see VARARGIN)

            % Choose default command line output for lvd_EditGeometricPointsGUI
            handles.output = hObject;
            
            centerUIFigure(app.lvd_EditGeometricPointsGUI);
            applySelectedThemeToApp(app);

            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            populateGUI(app, handles, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditGeometricPointsGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditGeometricPointsGUI);
        end

        % Button pushed function: addPointButton
        function addPointButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addPointButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditGeometricPointsGUI,'lvdData');

            listboxStr = GeometricPointEnum.getListBoxStr();
            out = AppDesignerGUIOutput();
            listdlgARH_App('ListString', listboxStr, ...
                'SelectionMode', 'single', ...
                'ListSize', [300 300], ...
                'InitialValue',1, ...
                'Name', 'Select New Point Type', ...
                'PromptString', 'Select the type of the point to be created:', ...
                'out',out);
            Selection = out.output{1};
            ok = out.output{2};

            if(ok)
                enum = GeometricPointEnum.getEnumForListboxStr(listboxStr{Selection});

                switch enum
                    case GeometricPointEnum.FixedInFrame
                        frame = lvdData.getDefaultInitialBodyInfo(lvdData.celBodyData).getBodyCenteredInertialFrame();
                        newPoint = FixedPointInFrame([0;0;0], frame, 'New Point', lvdData);

                    case GeometricPointEnum.CelestialBody
                        bodyInfo = lvdData.getDefaultInitialBodyInfo(lvdData.celBodyData);
                        newPoint = CelestialBodyPoint(bodyInfo, 'New Point');

                    case GeometricPointEnum.GroundObj
                        numGroundObjs = lvdData.groundObjs.getNumGroundObj();
                        if(numGroundObjs >= 1)
                            groundObj = lvdData.groundObjs.getGroundObjAtInd(1);
                            newPoint = GroundObjectPoint(groundObj, 'New Point');
                        else
                            uialert(app.lvd_EditGeometricPointsGUI, 'Cannot create ground object point: no ground objects exist in mission.  Create a new ground object first!', 'Cannot Create Point',"Modal",true);
                            return;
                        end

                    case GeometricPointEnum.Vehicle
                        newPoint = VehiclePoint('New Point');

                    case GeometricPointEnum.TwoBody
                        bodyInfo = lvdData.getDefaultInitialBodyInfo(lvdData.celBodyData);
                        elemSet = KeplerianElementSet(0, bodyInfo.radius+100, 0, 0, 0, 0, 0, bodyInfo.getBodyCenteredInertialFrame());
                        newPoint = TwoBodyPoint(elemSet, 'New Point', lvdData);

                    case GeometricPointEnum.LvdData
                        newPoint = LvdDataPoint(lvdData, lvdData, 'New Point');

                    case GeometricPointEnum.LagrangePoint
                        bodyInfo = LvdData.getDefaultInitialBodyInfo(lvdData.celBodyData);
                        if(not(isempty(bodyInfo.childrenBodyInfo)))
                            primaryBody = bodyInfo;
                            secondaryBody = bodyInfo.childrenBodyInfo(1);
                        else
                            primaryBody = bodyInfo.getParBodyInfo();
                            secondaryBody = bodyInfo;
                        end
            
                        originPt = TwoBodyRotatingFrameOriginEnum.Primary;
            
                        frame = TwoBodyRotatingFrame(primaryBody, secondaryBody, originPt, bodyInfo.celBodyData);

                        newPoint = LagrangeGeometricPoint(LagrangeGeometricPointEnum.L1, frame, lvdData, 'New Point');

                    case GeometricPointEnum.VectorPlaneIntersection
                        numPoints = lvdData.geometry.points.getNumPoints();
                        numVectors = lvdData.geometry.vectors.getNumVectors();
                        numPlanes = lvdData.geometry.planes.getNumPlanes();
                        if(numPoints >= 1 && numVectors >= 1 && numPlanes >= 1)
                            originPoint = lvdData.geometry.points.getPointAtInd(1);
                            vector = lvdData.geometry.vectors.getVectorAtInd(1);
                            plane = lvdData.geometry.planes.getPlaneAtInd(1);
                            newPoint = VectorPlaneIntersectionPoint(originPoint, vector, plane, 'New Point', lvdData);
                        else
                            uialert(app.lvd_EditGeometricPointsGUI, 'Cannot create vector-plane intersection point: There must be at least one geometric point, one geometric vector, and one geometric plane available.', 'Cannot Create Point',"Modal",true);
                            return;
                        end

                    case GeometricPointEnum.EphemerisFile
                        frame = lvdData.getDefaultInitialBodyInfo(lvdData.celBodyData).getBodyCenteredInertialFrame();
                        newPoint = EphemerisFilePoint('', frame, 'New Point', lvdData);

                    otherwise
                        error('Unknown Point Type Type: %s', string(enum))
                end

                useTF = newPoint.openEditDialog();

                if(useTF)
                    lvdData.geometry.points.addPoint(newPoint);
                app.pointsSearchText.Value = '';
                [~, ~] = app.setListboxStr('');
                app.pointsListBox.Value = newPoint;
                end
            end
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to closeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditGeometricPointsGUI);
        end

        % Window key press function: lvd_EditGeometricPointsGUI
        function lvd_EditGeometricPointsGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
 
            % hObject    handle to figure
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            switch(eventdata.Key)
                case 'return'
                    if(app.lvd_EditGeometricPointsGUI.CurrentObject == app.pointsSearchText)
                        return;
                    end
                    close(handles.lvd_EditGeometricPointsGUI);
                case 'enter'
                    if(app.lvd_EditGeometricPointsGUI.CurrentObject == app.pointsSearchText)
                        return;
                    end
                    close(handles.lvd_EditGeometricPointsGUI);
                case 'escape'
                    close(handles.lvd_EditGeometricPointsGUI);
            end
        end

        % Value changed function: pointsListBox
        function pointsListBox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pointsListBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns pointsListBox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from pointsListBox
            lvdData = getappdata(app.lvd_EditGeometricPointsGUI,'lvdData');
            if(strcmpi(get(app.lvd_EditGeometricPointsGUI,'SelectionType'),'open') && ...
                    lvdData.geometry.points.getNumPoints() > 0)
                selPt = app.pointsListBox.Value;
                selPt.openEditDialog();

                [~, ~] = app.setListboxStr();
            end
        end

        % Button pushed function: removePointButton
        function removePointButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removePointButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditGeometricPointsGUI,'lvdData');

            oldInd = app.pointsListBox.ValueIndex;
            selPt = app.pointsListBox.Value;

            tf = selPt.isInUse(lvdData);

            if(tf == false)
                lvdData.geometry.points.removePoint(selPt);

                lvdData.viewSettings.removeGeoPointFromViewProfiles(selPt);

                [~, visibleObjs] = app.setListboxStr(app.pointsSearchText.Value);
                if(not(isempty(visibleObjs)))
                app.pointsListBox.Value = visibleObjs(min(oldInd, numel(visibleObjs)));
                end
            else
                msg = sprintf('Could not delete the geometric point "%s" because it is in use as part of a vector, coordinate system, or reference frame.  Remove the point dependencies before attempting to delete the point.', selPt.getName());
%                 warndlg(msg,'Cannot Delete Point','modal');
                uialert(app.lvd_EditGeometricPointsGUI, msg, 'Cannot Delete Point',"Modal",true);
            end
        end

        % Window button down function: lvd_EditGeometricPointsGUI
        function lvd_EditGeometricPointsGUIWindowButtonDown(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            selType = app.lvd_EditGeometricPointsGUI.SelectionType;
            curObj = app.lvd_EditGeometricPointsGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.pointsListBox && numel(app.pointsListBox.ItemsData) > 0)
                    selPt = app.pointsListBox.Value;
                    selPt.openEditDialog();
    
                    [~, ~] = app.setListboxStr();
                end
            end
        end

 
        % Value changing function: pointsSearchText
        function pointsSearchTextValueChanging(app, event)
            [~, ~] = app.setListboxStr(event.Value);
        end
        % Value changed function: pointsSearchText
        function pointsSearchTextValueChanged(app, event)
            [~, ~] = app.setListboxStr(event.Value);
        end
    end
    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditGeometricPointsGUI = uifigure('Visible', 'off');
            app.lvd_EditGeometricPointsGUI.Name = 'Edit Points';
            app.lvd_EditGeometricPointsGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditGeometricPointsGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_EditGeometricPointsGUIWindowButtonDown, true);
            app.lvd_EditGeometricPointsGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditGeometricPointsGUI_WindowKeyPressFcn, true);
            app.lvd_EditGeometricPointsGUI.Tag = 'lvd_EditGeometricPointsGUI';
            app.GridLayout = uigridlayout(app.lvd_EditGeometricPointsGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Points';
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Icon = 'close_window(1).png';
            app.closeButton.Text = 'Close';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.pointsSearchText = uieditfield(app.GridLayout2, 'text');
            app.pointsSearchText.ValueChangingFcn = createCallbackFcn(app, @pointsSearchTextValueChanging, true);
            app.pointsSearchText.ValueChangedFcn = createCallbackFcn(app, @pointsSearchTextValueChanged, true);
            app.pointsSearchText.Tag = 'pointsSearchText';
            app.pointsSearchText.Tooltip = 'Type to filter the points below.';
            app.pointsSearchText.Placeholder = 'Search points...';
            app.pointsListBox = uilistbox(app.GridLayout2);
            app.pointsListBox.Items = {'Listbox'};
            app.pointsListBox.ValueChangedFcn = createCallbackFcn(app, @pointsListBox_Callback, true);
            app.pointsListBox.Tag = 'pointsListBox';
            app.pointsListBox.Tooltip = 'Double click an entry to edit it.';
            app.pointsListBox.Value = 'Listbox';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.addPointButton = uibutton(app.GridLayout3, 'push');
            app.addPointButton.ButtonPushedFcn = createCallbackFcn(app, @addPointButton_Callback, true);
            app.addPointButton.Tag = 'addPointButton';
            app.addPointButton.Icon = 'add.png';
            app.addPointButton.Text = 'Add Point';
            app.removePointButton = uibutton(app.GridLayout3, 'push');
            app.removePointButton.ButtonPushedFcn = createCallbackFcn(app, @removePointButton_Callback, true);
            app.removePointButton.Tag = 'removePointButton';
            app.removePointButton.Icon = 'remove.png';
            app.removePointButton.Text = 'Remove Point';
            % Show the figure after all components are created
            app.lvd_EditGeometricPointsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditGeometricPointsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditGeometricPointsGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditGeometricPointsGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditGeometricPointsGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditGeometricPointsGUI)
        end
    end
end