classdef lvd_EditLagrangePointGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditPointUIFigure          matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        uipanel1                   matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        TwoBodyRotatingFramePanel  matlab.ui.container.Panel
        GridLayout10               matlab.ui.container.GridLayout
        LagrangePointCombo         matlab.ui.control.DropDown
        EditTwoBodyRotatingFramePropertiesButton  matlab.ui.control.Button
        uipanel5                   matlab.ui.container.Panel
        GridLayout9                matlab.ui.container.GridLayout
        Image2                     matlab.ui.control.Image
        Image                      matlab.ui.control.Image
        dispTrajCheckbox           matlab.ui.control.CheckBox
        pointLineSpecCombo         matlab.ui.control.DropDown
        pointLineColorCombo        matlab.ui.control.DropDown
        text30                     matlab.ui.control.Label
        pointMarkerShapeCombo      matlab.ui.control.DropDown
        pointColorCombo            matlab.ui.control.DropDown
        text29                     matlab.ui.control.Label
        uipanel2                   matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        pointNameText              matlab.ui.control.EditField
        stageName                  matlab.ui.control.Label
        GridLayout2                matlab.ui.container.GridLayout
        cancelButton               matlab.ui.control.Button
        saveAndCloseButton         matlab.ui.control.Button
        text2                      matlab.ui.control.Label
    end

    properties (Access = private)
        output AppDesignerGUIOutput
        point LagrangeGeometricPoint
        lvdData LvdData
    end

    methods (Access = private)
        function populateGUI(app)
            app.pointNameText.Value = app.point.name;

            [listBoxStr, m] = LagrangeGeometricPointEnum.getListBoxStr();
            app.LagrangePointCombo.Items = listBoxStr;
            app.LagrangePointCombo.ItemsData = m;
            app.LagrangePointCombo.Value = app.point.lpoint;

            lvdSetupColorPicker(app.EditPointUIFigure, app.GridLayout9, 2, 2, app.pointColorCombo, lvd_colorSpecToRGB(app.point.markerColor), 'The color of the point marker on the display.', 'markerColor');

            [listBoxStr, m] = MarkerStyleEnum.getListboxStr();
            app.pointMarkerShapeCombo.Items = listBoxStr;
            app.pointMarkerShapeCombo.ItemsData = m;
            app.pointMarkerShapeCombo.Value = app.point.markerShape;

            lvdSetupColorPicker(app.EditPointUIFigure, app.GridLayout9, 2, 3, app.pointLineColorCombo, lvd_colorSpecToRGB(app.point.trkLineColor), 'The color of the point track line on the display.', 'trkLineColor');

            [listBoxStr, m] = LineSpecEnum.getListboxStr();
            app.pointLineSpecCombo.Items = listBoxStr;
            app.pointLineSpecCombo.ItemsData = m;
            app.pointLineSpecCombo.Value = app.point.trkLineSpec;

            app.dispTrajCheckbox.Value = app.point.plotTrkLine;
        end

        function errMsg = validateInputs(app)
            errMsg = {};

            if(isempty(strtrim(app.pointNameText.Value)))
                errMsg{end+1} = 'Point name must contain more than white space and must not be empty.';
            end
        end

        function saveAndClose(app)
            app.point.setName(app.pointNameText.Value);

            app.point.lpoint = app.LagrangePointCombo.Value;

            app.point.markerColor = lvdGetColorPickerRGB(app.EditPointUIFigure, 'markerColor', app.point.markerColor);
            app.point.markerShape = app.pointMarkerShapeCombo.Value;
            app.point.trkLineColor = lvdGetColorPickerRGB(app.EditPointUIFigure, 'trkLineColor', app.point.trkLineColor);
            app.point.trkLineSpec = app.pointLineSpecCombo.Value;
            app.point.plotTrkLine = app.dispTrajCheckbox.Value;
                
            app.output.output = {true};
            close(app.EditPointUIFigure);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, point, lvdData, output)
            centerUIFigure(app.EditPointUIFigure);
            applySelectedThemeToApp(app);

            app.point = point;
            app.output = output;
            app.lvdData = lvdData;

            app.populateGUI();

            uiwait(app.EditPointUIFigure);
        end

        % Button pushed function: EditTwoBodyRotatingFramePropertiesButton
        function EditTwoBodyRotatingFramePropertiesButtonPushed(app, event)
            app.point.frame.editFrameDialogUI();
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButtonPushed(app, event)
            errMsg = app.validateInputs();

            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.EditPointUIFigure, errMsg, 'Invalid Point Inputs', "Icon", "error");
            end
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            close(app.EditPointUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditPointUIFigure = uifigure('Visible', 'off');
            app.EditPointUIFigure.Name = 'Edit Point';
            app.EditPointUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout = uigridlayout(app.EditPointUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Point';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButtonPushed, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Name';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4 = uigridlayout(app.uipanel2);
            app.stageName = uilabel(app.GridLayout4);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Point Name';
            app.pointNameText = uieditfield(app.GridLayout4, 'text');
            app.pointNameText.Tag = 'pointNameText';
            app.pointNameText.Value = 'POINT NAME';
            app.uipanel5 = uipanel(app.GridLayout3);
            app.uipanel5.Title = 'Display';
            app.uipanel5.Tag = 'uipanel5';
            app.GridLayout9 = uigridlayout(app.uipanel5);
            app.text29 = uilabel(app.GridLayout9);
            app.text29.Tag = 'text29';
            app.text29.Text = 'Point Marker';
            app.pointColorCombo = uidropdown(app.GridLayout9);
            app.pointColorCombo.Items = {'Pop-up Menu'};
            app.pointColorCombo.Tag = 'pointColorCombo';
            app.pointColorCombo.Tooltip = 'The color of the point marker on the display.';
            app.pointColorCombo.Value = 'Pop-up Menu';
            app.pointMarkerShapeCombo = uidropdown(app.GridLayout9);
            app.pointMarkerShapeCombo.Items = {'Pop-up Menu'};
            app.pointMarkerShapeCombo.Tag = 'pointMarkerShapeCombo';
            app.pointMarkerShapeCombo.Tooltip = 'The shape of the point marker on the display.';
            app.pointMarkerShapeCombo.Value = 'Pop-up Menu';
            app.text30 = uilabel(app.GridLayout9);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Point Trajectory';
            app.pointLineColorCombo = uidropdown(app.GridLayout9);
            app.pointLineColorCombo.Items = {'Pop-up Menu'};
            app.pointLineColorCombo.Tag = 'pointLineColorCombo';
            app.pointLineColorCombo.Tooltip = 'The color of the point track line on the display.';
            app.pointLineColorCombo.Value = 'Pop-up Menu';
            app.pointLineSpecCombo = uidropdown(app.GridLayout9);
            app.pointLineSpecCombo.Items = {'Pop-up Menu'};
            app.pointLineSpecCombo.Tag = 'pointLineSpecCombo';
            app.pointLineSpecCombo.Tooltip = 'The style of the line of the point track line on the display.';
            app.pointLineSpecCombo.Value = 'Pop-up Menu';
            app.dispTrajCheckbox = uicheckbox(app.GridLayout9);
            app.dispTrajCheckbox.Tag = 'dispTrajCheckbox';
            app.dispTrajCheckbox.Text = 'Display Trajectory';
            app.Image = uiimage(app.GridLayout9);
            app.Image.ImageSource = 'color_wheel.png';
            app.Image2 = uiimage(app.GridLayout9);
            app.Image2.ImageSource = 'line.png';
            app.TwoBodyRotatingFramePanel = uipanel(app.GridLayout3);
            app.TwoBodyRotatingFramePanel.Title = 'Two Body Rotating Frame';
            app.GridLayout10 = uigridlayout(app.TwoBodyRotatingFramePanel);
            app.EditTwoBodyRotatingFramePropertiesButton = uibutton(app.GridLayout10, 'push');
            app.EditTwoBodyRotatingFramePropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @EditTwoBodyRotatingFramePropertiesButtonPushed, true);
            app.EditTwoBodyRotatingFramePropertiesButton.Icon = 'reference_frame.png';
            app.EditTwoBodyRotatingFramePropertiesButton.Text = 'Edit Two Body Rotating Frame Properties';
            app.LagrangePointCombo = uidropdown(app.GridLayout10);
            % Show the figure after all components are created
            app.EditPointUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditLagrangePointGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditPointUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditPointUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditPointUIFigure)
        end
    end
end