classdef lvd_EditLvdTrajectoryPointGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditLvdTrajectoryPointGUI  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        uipanel1                       matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        uipanel5                       matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        dispTrajCheckbox               matlab.ui.control.CheckBox
        pointLineSpecCombo             matlab.ui.control.DropDown
        pointLineColorCombo            matlab.ui.control.DropDown
        text30                         matlab.ui.control.Label
        pointMarkerShapeCombo          matlab.ui.control.DropDown
        pointColorCombo                matlab.ui.control.DropDown
        text29                         matlab.ui.control.Label
        uipanel3                       matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        TrajStartEndTimesLabel         matlab.ui.control.Label
        GridLayout5                    matlab.ui.container.GridLayout
        FileSelector                   wt.FileSelector
        text5                          matlab.ui.control.Label
        uipanel2                       matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        pointNameText                  matlab.ui.control.EditField
        stageName                      matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        cancelButton                   matlab.ui.control.Button
        saveAndCloseButton             matlab.ui.control.Button
        text2                          matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
        point LvdDataPoint
        thisLvdData LvdData
        loadedLvdData LvdData
    end

    methods (Access = private)
        function populateGUI(app)
            app.pointNameText.Value = app.point.getName();    

            if(app.point.hasValidLvdData())
                [startUT, endUT] = app.point.inputLvdData.stateLog.getStartAndEndTimes();
                app.TrajStartEndTimesLabel.Text = sprintf('Trajectory Start Time: %0.3f sec UT | Trajectory End Time: %0.3f sec UT', startUT, endUT);
            else
                app.TrajStartEndTimesLabel.Text = '';
            end

            lvdSetupColorPicker(app.lvd_EditLvdTrajectoryPointGUI, app.GridLayout9, 2, 2, app.pointColorCombo, lvd_colorSpecToRGB(app.point.markerColor), 'The color of the point marker on the display.', 'markerColor');

            [listBoxStr, m] = MarkerStyleEnum.getListboxStr();
            app.pointMarkerShapeCombo.Items = listBoxStr;
            app.pointMarkerShapeCombo.ItemsData = m;
            app.pointMarkerShapeCombo.Value = app.point.markerShape;

            lvdSetupColorPicker(app.lvd_EditLvdTrajectoryPointGUI, app.GridLayout9, 2, 3, app.pointLineColorCombo, lvd_colorSpecToRGB(app.point.trkLineColor), 'The color of the point track line on the display.', 'trkLineColor');

            [listBoxStr, m] = LineSpecEnum.getListboxStr();
            app.pointLineSpecCombo.Items = listBoxStr;
            app.pointLineSpecCombo.ItemsData = m;
            app.pointLineSpecCombo.Value = app.point.trkLineSpec;

            app.dispTrajCheckbox.Value = app.point.plotTrkLine;
        end

        function errMsg = validateInputs(app)
            errMsg = {};

            if(isempty(strtrim(app.pointNameText.Value)))
                errMsg{end+1} = 'Point name must contain more than white space and must not be empty.';
            end

            if(~app.point.hasValidLvdData() && isempty(app.loadedLvdData))
                errMsg{end+1} = 'No valid LVD case file was loaded.';
            end
        end

        function saveAndClose(app)
            app.point.setName(app.pointNameText.Value);

            if(not(isempty(app.loadedLvdData)))
                hPrgDlg = uiprogressdlg(app.lvd_EditLvdTrajectoryPointGUI, "Icon","info", "Indeterminate","on", ...
                                        "Message","Updating point with LVD case data, please wait...", "Title","Loading LVD Case");

                app.point.loadLvdData(app.loadedLvdData, app.thisLvdData);

                close(hPrgDlg);
            end

            app.point.markerColor = lvdGetColorPickerRGB(app.lvd_EditLvdTrajectoryPointGUI, 'markerColor', app.point.markerColor);
            app.point.markerShape = app.pointMarkerShapeCombo.Value;
            app.point.trkLineColor = lvdGetColorPickerRGB(app.lvd_EditLvdTrajectoryPointGUI, 'trkLineColor', app.point.trkLineColor);
            app.point.trkLineSpec = app.pointLineSpecCombo.Value;
            app.point.plotTrkLine = app.dispTrajCheckbox.Value;
                
            app.output.output = {true};
            close(app.lvd_EditLvdTrajectoryPointGUI);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditCelestialBodyPointGUI_OpeningFcn(app, point, output, lvdData)
            centerUIFigure(app.lvd_EditLvdTrajectoryPointGUI);
            applySelectedThemeToApp(app);

            app.point = point;
            app.output = output;
            app.thisLvdData = lvdData;

            app.populateGUI();

            uiwait(app.lvd_EditLvdTrajectoryPointGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            close(app.lvd_EditLvdTrajectoryPointGUI);
        end

        % Window key press function: lvd_EditLvdTrajectoryPointGUI
        function lvd_EditLvdTrajectoryPointGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditCelestialBodyPointGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditCelestialBodyPointGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            errMsg = app.validateInputs();

            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.lvd_EditLvdTrajectoryPointGUI, errMsg, 'Invalid Point Inputs', "Icon", "error");
            end
        end

        % Callback function: FileSelector
        function FileSelectorValueChanged(app, event)
            hPrgDlg = uiprogressdlg(app.lvd_EditLvdTrajectoryPointGUI, "Icon","info", "Indeterminate","on", ...
                                    "Message","Loading LVD case file, please wait...", "Title","Loading LVD Case");
            
            filePath = app.FileSelector.Value;
            
            try
                load(filePath); %#ok<LOAD>
            catch ME %#ok<NASGU>
                msg = sprintf('There was a problem loading the case file from disk: %s\n\n.  Case not loaded.', filePath);
                uialert(app.lvd_EditLvdTrajectoryPointGUI, msg, 'Load Error', 'Icon','error', 'Modal',true);

                app.loadedLvdData = LvdData.empty(1,0);

                return;
            end

            if(exist('lvdData','var') && isa(lvdData, 'LvdData'))
                stateLog = lvdData.script.executeScript(false, lvdData.script.getEventForInd(1), true, false, false, false);
                entries = stateLog.getAllEntries();   
                for(i=1:length(entries)) %#ok<*NO4LP> 
                    entry = entries(i);
                    bodyInfo = entry.centralBody;
                    bodyId = bodyInfo.id;

                    if(isempty(app.thisLvdData.celBodyData.getBodyInfoById(bodyId)))
                        msg = sprintf('The celestial body "%s" from the loaded LVD case file is not present is the current case.  Only LVD files with a trajectory around celestial bodies that exist in the current case can be loaded.', bodyInfo.name);
                        uialert(app.lvd_EditLvdTrajectoryPointGUI, msg, 'Load Error', 'Icon','error', 'Modal',true);
        
                        app.loadedLvdData = LvdData.empty(1,0);

                        return;
                    end
                end

                app.loadedLvdData = lvdData;

                [startUT, endUT] = lvdData.stateLog.getStartAndEndTimes();
                app.TrajStartEndTimesLabel.Text = sprintf('Trajectory Start Time: %0.3f sec UT | Trajectory End Time: %0.3f sec UT', startUT, endUT);
            else
                msg = sprintf('There was a problem loading the case file from disk: %s\n\n.  Case not loaded.', filePath);
                uialert(app.lvd_EditLvdTrajectoryPointGUI, msg, 'Load Error', 'Icon','error', 'Modal',true);

                app.loadedLvdData = LvdData.empty(1,0);

                return;
            end

            close(hPrgDlg);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditLvdTrajectoryPointGUI = uifigure('Visible', 'off');
            app.lvd_EditLvdTrajectoryPointGUI.Name = 'Edit Point';
            app.lvd_EditLvdTrajectoryPointGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditLvdTrajectoryPointGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditLvdTrajectoryPointGUI_WindowKeyPressFcn, true);
            app.lvd_EditLvdTrajectoryPointGUI.Tag = 'lvd_EditLvdTrajectoryPointGUI';
            app.GridLayout = uigridlayout(app.lvd_EditLvdTrajectoryPointGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Point';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Name';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4 = uigridlayout(app.uipanel2);
            app.stageName = uilabel(app.GridLayout4);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Point Name';
            app.pointNameText = uieditfield(app.GridLayout4, 'text');
            app.pointNameText.Tag = 'pointNameText';
            app.pointNameText.Value = 'POINT NAME';
            app.uipanel3 = uipanel(app.GridLayout3);
            app.uipanel3.Title = 'LVD Data';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout10 = uigridlayout(app.uipanel3);
            app.GridLayout5 = uigridlayout(app.GridLayout10);
            app.text5 = uilabel(app.GridLayout5);
            app.text5.Tag = 'text5';
            app.text5.Text = 'LVD Case MAT File';
            app.FileSelector = wt.FileSelector(app.GridLayout5);
            app.FileSelector.ValueChangedFcn = createCallbackFcn(app, @FileSelectorValueChanged, true);
            app.TrajStartEndTimesLabel = uilabel(app.GridLayout10);
            app.TrajStartEndTimesLabel.Text = 'Trajectory Start Time: %s sec | Trajectory End Time: %s sec';
            app.uipanel5 = uipanel(app.GridLayout3);
            app.uipanel5.Title = 'Display';
            app.uipanel5.Tag = 'uipanel5';
            app.GridLayout9 = uigridlayout(app.uipanel5);
            app.text29 = uilabel(app.GridLayout9);
            app.text29.Tag = 'text29';
            app.text29.Text = 'Point Marker';
            app.pointColorCombo = uidropdown(app.GridLayout9);
            app.pointColorCombo.Items = {'Pop-up Menu'};
            app.pointColorCombo.Tag = 'pointColorCombo';
            app.pointColorCombo.Tooltip = 'The color of the point marker on the display.';
            app.pointColorCombo.Value = 'Pop-up Menu';
            app.pointMarkerShapeCombo = uidropdown(app.GridLayout9);
            app.pointMarkerShapeCombo.Items = {'Pop-up Menu'};
            app.pointMarkerShapeCombo.Tag = 'pointMarkerShapeCombo';
            app.pointMarkerShapeCombo.Tooltip = 'The shape of the point marker on the display.';
            app.pointMarkerShapeCombo.Value = 'Pop-up Menu';
            app.text30 = uilabel(app.GridLayout9);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Point Trajectory';
            app.pointLineColorCombo = uidropdown(app.GridLayout9);
            app.pointLineColorCombo.Items = {'Pop-up Menu'};
            app.pointLineColorCombo.Tag = 'pointLineColorCombo';
            app.pointLineColorCombo.Tooltip = 'The color of the point track line on the display.';
            app.pointLineColorCombo.Value = 'Pop-up Menu';
            app.pointLineSpecCombo = uidropdown(app.GridLayout9);
            app.pointLineSpecCombo.Items = {'Pop-up Menu'};
            app.pointLineSpecCombo.Tag = 'pointLineSpecCombo';
            app.pointLineSpecCombo.Tooltip = 'The style of the line of the point track line on the display.';
            app.pointLineSpecCombo.Value = 'Pop-up Menu';
            app.dispTrajCheckbox = uicheckbox(app.GridLayout9);
            app.dispTrajCheckbox.Tag = 'dispTrajCheckbox';
            app.dispTrajCheckbox.Text = 'Display Trajectory';
            app.Image = uiimage(app.GridLayout9);
            app.Image.ImageSource = 'color_wheel.png';
            app.Image2 = uiimage(app.GridLayout9);
            app.Image2.ImageSource = 'line.png';
            % Show the figure after all components are created
            app.lvd_EditLvdTrajectoryPointGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditLvdTrajectoryPointGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditLvdTrajectoryPointGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditCelestialBodyPointGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditLvdTrajectoryPointGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditLvdTrajectoryPointGUI)
        end
    end
end