classdef lvd_EditTwoBodyPointGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditTwoBodyPointGUI         matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        uipanel1                        matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        uipanel5                        matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        dispTrajCheckbox                matlab.ui.control.CheckBox
        pointLineSpecCombo              matlab.ui.control.DropDown
        pointLineColorCombo             matlab.ui.control.DropDown
        text30                          matlab.ui.control.Label
        pointMarkerShapeCombo           matlab.ui.control.DropDown
        pointColorCombo                 matlab.ui.control.DropDown
        text29                          matlab.ui.control.Label
        uipanel7                        matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        orbit6UnitLabel                 matlab.ui.control.Label
        orbit5UnitLabel                 matlab.ui.control.Label
        orbit4UnitLabel                 matlab.ui.control.Label
        orbit3UnitLabel                 matlab.ui.control.Label
        orbit2UnitLabel                 matlab.ui.control.Label
        orbit1UnitLabel                 matlab.ui.control.Label
        text34                          matlab.ui.control.Label
        orbit6Text                      matlab.ui.control.EditField
        orbit5Text                      matlab.ui.control.EditField
        orbit4Text                      matlab.ui.control.EditField
        orbit3Text                      matlab.ui.control.EditField
        orbit2Text                      matlab.ui.control.EditField
        orbit1Text                      matlab.ui.control.EditField
        utText                          matlab.ui.control.EditField
        orbit6Label                     matlab.ui.control.Label
        orbit5Label                     matlab.ui.control.Label
        orbit4Label                     matlab.ui.control.Label
        orbit3Label                     matlab.ui.control.Label
        orbit2Label                     matlab.ui.control.Label
        orbit1Label                     matlab.ui.control.Label
        text33                          matlab.ui.control.Label
        uipanel6                        matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        GridLayout6                     matlab.ui.container.GridLayout
        refFrameTypeCombo               matlab.ui.control.DropDown
        setFrameOptionsButton           matlab.ui.control.Button
        elementSetCombo                 matlab.ui.control.DropDown
        text32                          matlab.ui.control.Label
        text31                          matlab.ui.control.Label
        uipanel2                        matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        pointNameText                   matlab.ui.control.EditField
        stageName                       matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        cancelButton                    matlab.ui.control.Button
        saveAndCloseButton              matlab.ui.control.Button
        text2                           matlab.ui.control.Label
        epochContextMenu                matlab.ui.container.ContextMenu
        enterUTAsDateTimeContextMenu    matlab.ui.container.Menu
        orbitPanelContextMenu           matlab.ui.container.ContextMenu
        getOrbitFromSFSFileContextMenu  matlab.ui.container.Menu
        getOrbitFromKSPTOTConnectContextMenu  matlab.ui.container.Menu
        getOrbitFromKSPActiveVesselMenu  matlab.ui.container.Menu
        copyOrbitToClipboardMenu        matlab.ui.container.Menu
        pasteOrbitFromClipboardMenu     matlab.ui.container.Menu
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function [orbitModel, elemSetEnum] = getCurrentElemSetFromExistingValues(app, handles)
            contents = cellstr(get(handles.elementSetCombo,'String'));
            selElemSet = contents{get(handles.elementSetCombo,'Value')};
            elemSetEnum = ElementSetEnum.getEnumForListboxStr(selElemSet);

            time = str2double(handles.utText.String);

            orbit1Elem = str2double(handles.orbit1Text.String);
            orbit2Elem = str2double(handles.orbit2Text.String);
            orbit3Elem = str2double(handles.orbit3Text.String);
            orbit4Elem = str2double(handles.orbit4Text.String);
            orbit5Elem = str2double(handles.orbit5Text.String);
            orbit6Elem = str2double(handles.orbit6Text.String);

            curElemSet = getappdata(handles.lvd_EditTwoBodyPointGUI,'curElemSet');
            frame = curElemSet.frame;

            switch elemSetEnum
                case ElementSetEnum.CartesianElements
                    orbitModel = CartesianElementSet(time, [orbit1Elem; orbit2Elem; orbit3Elem], [orbit4Elem; orbit5Elem; orbit6Elem], frame);

                case ElementSetEnum.KeplerianElements
                    orbitModel = KeplerianElementSet(time, orbit1Elem, orbit2Elem, deg2rad(orbit3Elem), deg2rad(orbit4Elem), deg2rad(orbit5Elem), deg2rad(orbit6Elem), frame);

                case ElementSetEnum.GeographicElements
                    orbitModel = GeographicElementSet(time, deg2rad(orbit1Elem), deg2rad(orbit2Elem), orbit3Elem, deg2rad(orbit4Elem), deg2rad(orbit5Elem), orbit6Elem, frame);

                case ElementSetEnum.UniversalElements
                    orbitModel = UniversalElementSet(time, orbit1Elem, orbit2Elem, deg2rad(orbit3Elem), deg2rad(orbit4Elem), deg2rad(orbit5Elem), orbit6Elem, frame);

                otherwise
                    error('Unknown element set type: %s', class(elemSetEnum));
            end
        end

        function bodyInfo = getSelectedBodyInfo(app, handles)
            curElemSet = getappdata(handles.lvd_EditTwoBodyPointGUI,'curElemSet');
            bodyInfo = curElemSet.frame.getOriginBody();
        end

        function populateGUI(app, handles, point)
            set(handles.pointNameText,'String',point.getName());
            elemSet = point.elemSet;
            setappdata(handles.lvd_EditTwoBodyPointGUI,'curElemSet',elemSet);

            handles.refFrameTypeCombo.String = ReferenceFrameEnum.getListBoxStr();
            handles.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(elemSet.frame.typeEnum.name);
            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', elemSet.frame.getNameStr());

            handles.elementSetCombo.String = ElementSetEnum.getListBoxStr();
            value = ElementSetEnum.getIndForName(elemSet.typeEnum.name);
            handles.elementSetCombo.Value = value;
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            elementSetCombo_Callback(app, evt);

            handles.utText.String = fullAccNum2Str(elemSet.time);

            elemVector = elemSet.getElementVector();
            handles.orbit1Text.String = fullAccNum2Str(elemVector(1));
            handles.orbit2Text.String = fullAccNum2Str(elemVector(2));
            handles.orbit3Text.String = fullAccNum2Str(elemVector(3));
            handles.orbit4Text.String = fullAccNum2Str(elemVector(4));
            handles.orbit5Text.String = fullAccNum2Str(elemVector(5));
            handles.orbit6Text.String = fullAccNum2Str(elemVector(6));

            handles.pointColorCombo.String = ColorSpecEnum.getListboxStr();
            handles.pointColorCombo.Value = ColorSpecEnum.getIndForName(point.markerColor.name);

            handles.pointMarkerShapeCombo.String = MarkerStyleEnum.getListboxStr();
            handles.pointMarkerShapeCombo.Value = MarkerStyleEnum.getIndForName(point.markerShape.name);

            handles.pointLineColorCombo.String = ColorSpecEnum.getListboxStr();
            handles.pointLineColorCombo.Value = ColorSpecEnum.getIndForName(point.trkLineColor.name);

            handles.pointLineSpecCombo.String = LineSpecEnum.getListboxStr();
            handles.pointLineSpecCombo.Value = LineSpecEnum.getIndForName(point.trkLineSpec.name);

            handles.dispTrajCheckbox.Value = double(point.plotTrkLine);
        end

        function updateStateDueToFrameChange(app, handles, newFrame)
            lvdData = getappdata(handles.lvd_EditTwoBodyPointGUI,'lvdData');
            celBodyData = lvdData.celBodyData;

            curElemSet = getappdata(handles.lvd_EditTwoBodyPointGUI,'curElemSet');

            contents = cellstr(get(handles.refFrameTypeCombo,'String'));
            selFrameType = contents{get(handles.refFrameTypeCombo,'Value')};
            refFrameEnum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);

            if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
                refFrameEnum = newFrame.typeEnum;
                handles.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
            end

            switch refFrameEnum
                case ReferenceFrameEnum.BodyCenteredInertial
                    if(isempty(newFrame))
                        bodyInfo = getSelectedBodyInfo(app, handles);
                    else
                        bodyInfo = newFrame.getOriginBody();
                    end

                    newFrame = bodyInfo.getBodyCenteredInertialFrame();

                case ReferenceFrameEnum.BodyFixedRotating
                    if(isempty(newFrame))
                        bodyInfo = getSelectedBodyInfo(app, handles);
                    else
                        bodyInfo = newFrame.getOriginBody();
                    end

                    newFrame = bodyInfo.getBodyFixedFrame();

                case ReferenceFrameEnum.TwoBodyRotating
                    if(isempty(newFrame))
                        bodyInfo = getSelectedBodyInfo(app, handles);
                        if(not(isempty(bodyInfo.childrenBodyInfo)))
                            primaryBody = bodyInfo;
                            secondaryBody = bodyInfo.childrenBodyInfo(1);
                        else
                            primaryBody = bodyInfo.getParBodyInfo();
                            secondaryBody = bodyInfo;
                        end

                        originPt = TwoBodyRotatingFrameOriginEnum.Primary;

                        newFrame = TwoBodyRotatingFrame(primaryBody, secondaryBody, originPt, celBodyData);
                    else
                        newFrame = TwoBodyRotatingFrame(newFrame.primaryBodyInfo, newFrame.secondaryBodyInfo, newFrame.originPt, celBodyData);
                    end

                case ReferenceFrameEnum.UserDefined
                    if(isempty(newFrame))
                        numFrames = lvdData.geometry.refFrames.getNumRefFrames();
                        if(numFrames >= 1)
                            geometricFrame = AbstractGeometricRefFrame.empty(1,0);
                            for(i=1:numFrames) %#ok<*NO4LP> 
                                frame = lvdData.geometry.refFrames.getRefFrameAtInd(i);
                                if(frame.isVehDependent() == false)
                                    geometricFrame = frame;
                                    break;
                                end
                            end

                            if(not(isempty(geometricFrame)))
                                newFrame = UserDefinedGeometricFrame(geometricFrame, lvdData);
                            else
                                bodyInfo = getSelectedBodyInfo(app, handles);
                                newFrame = bodyInfo.getBodyCenteredInertialFrame();

                                warndlg('There are no geometric frames available which are not dependent on vehicle properties.  A body-centered inertial frame will be selected instead.');
                            end
                        else
                            bodyInfo = getSelectedBodyInfo(app, handles);
                            newFrame = bodyInfo.getBodyCenteredInertialFrame();

                            warndlg('There are no geometric frames available which are not dependent on vehicle properties.  A body-centered inertial frame will be selected instead.');
                        end

                    else
                        newFrame = UserDefinedGeometricFrame(newFrame.geometricFrame, lvdData);
                    end

                otherwise
                    error('Unknown reference frame type: %s', string(refFrameEnum));
            end

            if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
                refFrameEnum = newFrame.typeEnum;
                handles.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
            end

            try
                newElemSet = curElemSet.convertToFrame(newFrame);

                elemVect = newElemSet.getElementVector();

                if(any(isnan(elemVect)) || any(not(isfinite(elemVect))))
                    error('State conversion resulted in NaN');
                end

                handles.orbit1Text.String = fullAccNum2Str(elemVect(1));
                handles.orbit2Text.String = fullAccNum2Str(elemVect(2));
                handles.orbit3Text.String = fullAccNum2Str(elemVect(3));
                handles.orbit4Text.String = fullAccNum2Str(elemVect(4));
                handles.orbit5Text.String = fullAccNum2Str(elemVect(5));
                handles.orbit6Text.String = fullAccNum2Str(elemVect(6));

                setappdata(handles.lvd_EditTwoBodyPointGUI,'curElemSet', newElemSet);
            catch 
                updateValuesInState(app, handles);
                msgbox('Error converting state to new frame.','Conversion Error','error');
            end

            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', newFrame.getNameStr());
        end

        function updateValuesInState(app, handles)
            curElemSet = getappdata(handles.lvd_EditTwoBodyPointGUI,'curElemSet');
            newElemSet = curElemSet;

            time = str2double(handles.utText.String);
            orbit1Elem = str2double(handles.orbit1Text.String);
            orbit2Elem = str2double(handles.orbit2Text.String);
            orbit3Elem = str2double(handles.orbit3Text.String);
            orbit4Elem = str2double(handles.orbit4Text.String);
            orbit5Elem = str2double(handles.orbit5Text.String);
            orbit6Elem = str2double(handles.orbit6Text.String);

            frame = newElemSet.frame;
            switch newElemSet.typeEnum
                case ElementSetEnum.CartesianElements
                    newElemSet = CartesianElementSet(time, [orbit1Elem; orbit2Elem; orbit3Elem], [orbit4Elem; orbit5Elem; orbit6Elem], frame);

                case ElementSetEnum.KeplerianElements
                    newElemSet = KeplerianElementSet(time, orbit1Elem, orbit2Elem, deg2rad(orbit3Elem), deg2rad(orbit4Elem), deg2rad(orbit5Elem), deg2rad(orbit6Elem), frame);

                case ElementSetEnum.GeographicElements
                    newElemSet = GeographicElementSet(time, deg2rad(orbit1Elem), deg2rad(orbit2Elem), orbit3Elem, deg2rad(orbit4Elem), deg2rad(orbit5Elem), orbit6Elem, frame);

                case ElementSetEnum.UniversalElements
                    newElemSet = UniversalElementSet(time, orbit1Elem, orbit2Elem, deg2rad(orbit3Elem), deg2rad(orbit4Elem), deg2rad(orbit5Elem), orbit6Elem, frame);

                otherwise
                    error('Unknown element set type: %s', string(elemSetEnum));
            end

            setappdata(handles.lvd_EditTwoBodyPointGUI,'curElemSet', newElemSet);
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            if(isempty(strtrim(handles.pointNameText.String)))
                errMsg{end+1} = {'Point name must contain more than white space and must not be empty.'};
            end
        end


        function saveAndClose(app, handles)
            lvdData = getappdata(handles.lvd_EditTwoBodyPointGUI,'lvdData');
            point = getappdata(handles.lvd_EditTwoBodyPointGUI, 'point');

            point.setName(handles.pointNameText.String);

            [elemSet, ~] = getCurrentElemSetFromExistingValues(app, handles);
            point.elemSet = elemSet;

            str = handles.pointColorCombo.String{handles.pointColorCombo.Value};
            point.markerColor = ColorSpecEnum.getEnumForListboxStr(str);

            str = handles.pointMarkerShapeCombo.String{handles.pointMarkerShapeCombo.Value};
            point.markerShape = MarkerStyleEnum.getEnumForListboxStr(str);

            str = handles.pointLineColorCombo.String{handles.pointLineColorCombo.Value};
            point.trkLineColor = ColorSpecEnum.getEnumForListboxStr(str);

            str = handles.pointLineSpecCombo.String{handles.pointLineSpecCombo.Value};
            point.trkLineSpec = LineSpecEnum.getEnumForListboxStr(str);

            point.plotTrkLine = logical(handles.dispTrajCheckbox.Value);

            maStateLog = lvdData.stateLog.getMAFormattedStateLogMatrix(false);

            stateLogMinTime = min(maStateLog(:,1));
            if(stateLogMinTime > 0)
                minTime = -1;
            else
                minTime = stateLogMinTime;
            end

%             simMaxDur = lvdData.settings.simMaxDur;
            stateLogMaxTime = max(maStateLog(:,1));
            maxTime = stateLogMaxTime;
%             if(stateLogMinTime + simMaxDur > stateLogMaxTime)
%                 maxTime = stateLogMinTime + simMaxDur;
%             else
%                 maxTime = stateLogMaxTime;
%             end

            hProgDlg = uiprogressdlg(app.lvd_EditTwoBodyPointGUI, "Message",'The two body point is (re-)generating its internal state cache.  Please wait.', 'Title','Creating Cache', 'Icon','info', 'Indeterminate','on');
            point.refeshTrajCache(minTime, maxTime);
            if(isvalid(hProgDlg))
                close(hProgDlg);
            end

            app.output.output = {true};
            close(handles.lvd_EditTwoBodyPointGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditTwoBodyPointGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditTwoBodyPointGUI (see VARARGIN)

            % Choose default command line output for lvd_EditTwoBodyPointGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            

            point = varargin{1};
            setappdata(hObject, 'point', point);

            lvdData = varargin{2};
            setappdata(hObject, 'lvdData', lvdData);

            handles.ksptotMainGUI = varargin{3};

            app.output = varargin{4};
            
            populateGUI(app, handles, point);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditTwoBodyPointGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditTwoBodyPointGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditTwoBodyPointGUI);
        end

        % Menu selected function: copyOrbitToClipboardMenu
        function copyOrbitToClipboardMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to copyOrbitToClipboardMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            bodyInfo = getSelectedBodyInfo(app, handles);

            o1V = handles.orbit1Text.String;
            o2V = handles.orbit2Text.String;
            o3V = handles.orbit3Text.String;
            o4V = handles.orbit4Text.String;
            o5V = handles.orbit5Text.String;
            o6V = handles.orbit6Text.String;

            contents = cellstr(get(handles.refFrameTypeCombo,'String'));
            selFrameType = contents{get(handles.refFrameTypeCombo,'Value')};
            enum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);
            framEnumOrig = enum;
            if(not(enum == ReferenceFrameEnum.BodyCenteredInertial))
                enum = ReferenceFrameEnum.BodyCenteredInertial;
                ind = ReferenceFrameEnum.getIndForName(enum.name);
                handles.refFrameTypeCombo.Value = ind;
                refFrameTypeCombo_Callback(app, evt);
            end

            contents = cellstr(get(handles.elementSetCombo,'String'));
            selOrbitType = contents{get(handles.elementSetCombo,'Value')};
            [~,enum] = ElementSetEnum.getIndForName(selOrbitType);
            elemSetEnumOrig = enum;
            if(not(enum == ElementSetEnum.KeplerianElements))
                enum = ElementSetEnum.KeplerianElements;
                ind = ElementSetEnum.getIndForName(enum.name);
                handles.elementSetCombo.Value = ind;
                elementSetCombo_Callback(app, evt);
            end

            copyOrbitToClipboardFromText(handles.utText, handles.orbit1Text, handles.orbit2Text, ...
                handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, ...
                handles.orbit6Text, true, bodyInfo.id);

            ind = ElementSetEnum.getIndForName(elemSetEnumOrig.name);
            handles.elementSetCombo.Value = ind;
            elementSetCombo_Callback(app, evt);

            ind = ReferenceFrameEnum.getIndForName(framEnumOrig.name);
            handles.refFrameTypeCombo.Value = ind;
            refFrameTypeCombo_Callback(app, evt);

            handles.orbit1Text.String = o1V;
            handles.orbit2Text.String = o2V;
            handles.orbit3Text.String = o3V;
            handles.orbit4Text.String = o4V;
            handles.orbit5Text.String = o5V;
            handles.orbit6Text.String = o6V;
        end

        % Value changed function: elementSetCombo
        function elementSetCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.elementSetCombo;
            
            % hObject    handle to elementSetCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns elementSetCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from elementSetCombo
            contents = cellstr(get(hObject,'String'));
            sel = contents{get(hObject,'Value')};
            [~, elemSetEnum] = ElementSetEnum.getIndForName(sel);

            elemNames = elemSetEnum.elemNames;
            unitNames = elemSetEnum.unitNames;

            handles.orbit1Label.String = elemNames{1};
            handles.orbit2Label.String = elemNames{2};
            handles.orbit3Label.String = elemNames{3};
            handles.orbit4Label.String = elemNames{4};
            handles.orbit5Label.String = elemNames{5};
            handles.orbit6Label.String = elemNames{6};

            handles.orbit1UnitLabel.String = unitNames{1};
            handles.orbit2UnitLabel.String = unitNames{2};
            handles.orbit3UnitLabel.String = unitNames{3};
            handles.orbit4UnitLabel.String = unitNames{4};
            handles.orbit5UnitLabel.String = unitNames{5};
            handles.orbit6UnitLabel.String = unitNames{6};

            curElemSet = getappdata(handles.lvd_EditTwoBodyPointGUI,'curElemSet');

            try
                switch elemSetEnum
                    case ElementSetEnum.CartesianElements
                        newElemSet = curElemSet.convertToCartesianElementSet();

                    case ElementSetEnum.KeplerianElements
                        newElemSet = curElemSet.convertToKeplerianElementSet();

                    case ElementSetEnum.GeographicElements
                        newElemSet = curElemSet.convertToGeographicElementSet();

                    case ElementSetEnum.UniversalElements
                        newElemSet = curElemSet.convertToUniversalElementSet();

                    otherwise
                        error('Unknown element set type: %s', class(elemSetEnum));
                end

                elemVect = newElemSet.getElementVector();

                if(any(isnan(elemVect)) || any(not(isfinite(elemVect))))
                    error('State conversion resulted in NaN or Inf');
                end

                handles.orbit1Text.String = fullAccNum2Str(elemVect(1));
                handles.orbit2Text.String = fullAccNum2Str(elemVect(2));
                handles.orbit3Text.String = fullAccNum2Str(elemVect(3));
                handles.orbit4Text.String = fullAccNum2Str(elemVect(4));
                handles.orbit5Text.String = fullAccNum2Str(elemVect(5));
                handles.orbit6Text.String = fullAccNum2Str(elemVect(6));

                setappdata(handles.lvd_EditTwoBodyPointGUI,'curElemSet', newElemSet);

            catch
                updateValuesInState(app, handles);
                msgbox('Error converting state to new element set.','Conversion Error','error');
            end
        end

        % Menu selected function: enterUTAsDateTimeContextMenu
        function enterUTAsDateTimeContextMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to enterUTAsDateTimeContextMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            secUT = enterUTAsDateTimeGUI(str2double(get(gco, 'String')));
            if(secUT >= 0)
                set(gco, 'String', fullAccNum2Str(secUT));
                
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                utText_Callback(app, evt);
            end
        end

        % Menu selected function: getOrbitFromKSPActiveVesselMenu
        function getOrbitFromKSPActiveVesselMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getOrbitFromKSPActiveVesselMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            contents = cellstr(get(handles.refFrameTypeCombo,'String'));
            selFrameType = contents{get(handles.refFrameTypeCombo,'Value')};
            enum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);
            if(not(enum == ReferenceFrameEnum.BodyCenteredInertial))
                enum = ReferenceFrameEnum.BodyCenteredInertial;
                ind = ReferenceFrameEnum.getIndForName(enum.name);
                handles.refFrameTypeCombo.Value = ind;
                refFrameTypeCombo_Callback(app, evt);
            end

            contents = cellstr(get(handles.elementSetCombo,'String'));
            selOrbitType = contents{get(handles.elementSetCombo,'Value')};
            [~,enum] = ElementSetEnum.getIndForName(selOrbitType);
            if(not(enum == ElementSetEnum.KeplerianElements))
                enum = ElementSetEnum.KeplerianElements;
                ind = ElementSetEnum.getIndForName(enum.name);
                handles.elementSetCombo.Value = ind;
                elementSetCombo_Callback(app, evt);
            end

            refBodyID = orbitPanelGetOrbitFromKSPTOTConnectActiveVesselCallBack(handles.orbit1Text, handles.orbit2Text, handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, handles.orbit6Text, handles.utText);
            tru = computeTrueAnomFromMean(deg2rad(str2double(get(handles.orbit6Text,'String'))), str2double(get(handles.orbit2Text,'String')));
            set(handles.orbit6Text,'String',fullAccNum2Str(rad2deg(tru)));

            if(~isempty(refBodyID) && isnumeric(refBodyID))
                lvdData = getappdata(handles.lvd_EditTwoBodyPointGUI,'lvdData');
                celBodyData = lvdData.celBodyData;
                bodyInfo = getBodyInfoByNumber(refBodyID, celBodyData);

                curElemSet = getCurrentElemSetFromExistingValues(app, handles);
                setappdata(handles.lvd_EditTwoBodyPointGUI,'curElemSet',curElemSet);

                curElemSet.frame.setOriginBody(bodyInfo);
                updateStateDueToFrameChange(app, handles, AbstractReferenceFrame.empty(1,0));
            end
        end

        % Menu selected function: getOrbitFromKSPTOTConnectContextMenu
        function getOrbitFromKSPTOTConnectContextMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getOrbitFromKSPTOTConnectContextMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            contents = cellstr(get(handles.refFrameTypeCombo,'String'));
            selFrameType = contents{get(handles.refFrameTypeCombo,'Value')};
            enum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);
            if(not(enum == ReferenceFrameEnum.BodyCenteredInertial))
                enum = ReferenceFrameEnum.BodyCenteredInertial;
                ind = ReferenceFrameEnum.getIndForName(enum.name);
                handles.refFrameTypeCombo.Value = ind;
                refFrameTypeCombo_Callback(app, evt);
            end

            contents = cellstr(get(handles.elementSetCombo,'String'));
            selOrbitType = contents{get(handles.elementSetCombo,'Value')};
            [~,enum] = ElementSetEnum.getIndForName(selOrbitType);
            if(not(enum == ElementSetEnum.KeplerianElements))
                enum = ElementSetEnum.KeplerianElements;
                ind = ElementSetEnum.getIndForName(enum.name);
                handles.elementSetCombo.Value = ind;
                elementSetCombo_Callback(app, evt);
            end

            refBodyID = orbitPanelGetOrbitFromKSPTOTConnectCallBack(handles.orbit1Text, handles.orbit2Text, handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, handles.orbit6Text, handles.utText);
            tru = computeTrueAnomFromMean(deg2rad(str2double(get(handles.orbit6Text,'String'))), str2double(get(handles.orbit2Text,'String')));
            set(handles.orbit6Text,'String',fullAccNum2Str(rad2deg(tru)));

            if(~isempty(refBodyID) && isnumeric(refBodyID))
                lvdData = getappdata(handles.lvd_EditTwoBodyPointGUI,'lvdData');
                celBodyData = lvdData.celBodyData;
                bodyInfo = getBodyInfoByNumber(refBodyID, celBodyData);

                curElemSet = getCurrentElemSetFromExistingValues(app, handles);
                setappdata(handles.lvd_EditTwoBodyPointGUI,'curElemSet',curElemSet);

                curElemSet.frame.setOriginBody(bodyInfo);
                updateStateDueToFrameChange(app, handles, AbstractReferenceFrame.empty(1,0));
            end
        end

        % Menu selected function: getOrbitFromSFSFileContextMenu
        function getOrbitFromSFSFileContextMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getOrbitFromSFSFileContextMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            contents = cellstr(get(handles.refFrameTypeCombo,'String'));
            selFrameType = contents{get(handles.refFrameTypeCombo,'Value')};
            enum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);
            if(not(enum == ReferenceFrameEnum.BodyCenteredInertial))
                enum = ReferenceFrameEnum.BodyCenteredInertial;
                ind = ReferenceFrameEnum.getIndForName(enum.name);
                handles.refFrameTypeCombo.Value = ind;
                refFrameTypeCombo_Callback(app, evt);
            end

            contents = cellstr(get(handles.elementSetCombo,'String'));
            selOrbitType = contents{get(handles.elementSetCombo,'Value')};
            [~,enum] = ElementSetEnum.getIndForName(selOrbitType);
            if(not(enum == ElementSetEnum.KeplerianElements))
                enum = ElementSetEnum.KeplerianElements;
                ind = ElementSetEnum.getIndForName(enum.name);
                handles.elementSetCombo.Value = ind;
                elementSetCombo_Callback(app, evt);
            end

            refBodyID = orbitPanelGetOrbitFromSFSContextCallBack(handles.ksptotMainGUI, handles.orbit1Text, handles.orbit2Text, handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, handles.orbit6Text, handles.utText);
            tru = computeTrueAnomFromMean(deg2rad(str2double(get(handles.orbit6Text,'String'))), str2double(get(handles.orbit2Text,'String')));
            set(handles.orbit6Text,'String',fullAccNum2Str(rad2deg(tru)));

            if(~isempty(refBodyID) && isnumeric(refBodyID))
                lvdData = getappdata(handles.lvd_EditTwoBodyPointGUI,'lvdData');
                celBodyData = lvdData.celBodyData;
                bodyInfo = getBodyInfoByNumber(refBodyID, celBodyData);

                curElemSet = getCurrentElemSetFromExistingValues(app, handles);
                setappdata(handles.lvd_EditTwoBodyPointGUI,'curElemSet',curElemSet);

                curElemSet.frame.setOriginBody(bodyInfo);
                updateStateDueToFrameChange(app, handles, AbstractReferenceFrame.empty(1,0));
            end
        end

        % Window key press function: lvd_EditTwoBodyPointGUI
        function lvd_EditTwoBodyPointGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditTwoBodyPointGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditTwoBodyPointGUI);
            end
        end

        % Value changed function: orbit1Text
        function orbit1Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit1Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit1Text as text
            %        str2double(get(hObject,'String')) returns contents of orbit1Text as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            updateValuesInState(app, handles);
        end

        % Value changed function: orbit2Text
        function orbit2Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit2Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit2Text as text
            %        str2double(get(hObject,'String')) returns contents of orbit2Text as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            updateValuesInState(app, handles);
        end

        % Value changed function: orbit3Text
        function orbit3Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit3Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit3Text as text
            %        str2double(get(hObject,'String')) returns contents of orbit3Text as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            updateValuesInState(app, handles);
        end

        % Value changed function: orbit4Text
        function orbit4Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit4Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit4Text as text
            %        str2double(get(hObject,'String')) returns contents of orbit4Text as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            updateValuesInState(app, handles);
        end

        % Value changed function: orbit5Text
        function orbit5Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit5Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit5Text as text
            %        str2double(get(hObject,'String')) returns contents of orbit5Text as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            updateValuesInState(app, handles);
        end

        % Value changed function: orbit6Text
        function orbit6Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit6Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit6Text as text
            %        str2double(get(hObject,'String')) returns contents of orbit6Text as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            updateValuesInState(app, handles);
        end

        % Menu selected function: pasteOrbitFromClipboardMenu
        function pasteOrbitFromClipboardMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pasteOrbitFromClipboardMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            contents = cellstr(get(handles.refFrameTypeCombo,'String'));
            selFrameType = contents{get(handles.refFrameTypeCombo,'Value')};
            enum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);
            if(not(enum == ReferenceFrameEnum.BodyCenteredInertial))
                enum = ReferenceFrameEnum.BodyCenteredInertial;
                ind = ReferenceFrameEnum.getIndForName(enum.name);
                handles.refFrameTypeCombo.Value = ind;
                refFrameTypeCombo_Callback(app, evt);
            end

            contents = cellstr(get(handles.elementSetCombo,'String'));
            selOrbitType = contents{get(handles.elementSetCombo,'Value')};
            [~,enum] = ElementSetEnum.getIndForName(selOrbitType);
            if(not(enum == ElementSetEnum.KeplerianElements))
                enum = ElementSetEnum.KeplerianElements;
                ind = ElementSetEnum.getIndForName(enum.name);
                handles.elementSetCombo.Value = ind;
                elementSetCombo_Callback(app, evt);
            end

            lvdData = getappdata(handles.lvd_EditTwoBodyPointGUI,'lvdData');
            celBodyData = lvdData.celBodyData;

            bodyInfo = pasteOrbitFromClipboard(handles.utText, handles.orbit1Text, handles.orbit2Text, ...
                handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, ...
                handles.orbit6Text, true, [], celBodyData);

            if(not(isempty(bodyInfo)))
                curElemSet = getappdata(handles.lvd_EditTwoBodyPointGUI,'curElemSet');
                curElemSet.frame.setOriginBody(bodyInfo);
                updateStateDueToFrameChange(app, handles, AbstractReferenceFrame.empty(1,0));
            end
        end

        % Value changed function: refFrameTypeCombo
        function refFrameTypeCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
%             hObject = handles.refFrameTypeCombo;
            
            % hObject    handle to refFrameTypeCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns refFrameTypeCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from refFrameTypeCombo
            updateStateDueToFrameChange(app, handles, AbstractReferenceFrame.empty(1,0));
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
%                 uiresume(handles.lvd_EditTwoBodyPointGUI);
                app.saveAndClose(handles);
            else
%                 msgbox(errMsg,'Invalid Point Inputs','error');
                uialert(app.lvd_EditTwoBodyPointGUI, errMsg, 'Invalid Point Inputs', "Icon","error");
            end
        end

        % Button pushed function: setFrameOptionsButton
        function setFrameOptionsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to setFrameOptionsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            curElemSet = getappdata(handles.lvd_EditTwoBodyPointGUI,'curElemSet');
            frame = curElemSet.frame;
            newFrame = frame.editFrameDialogUI(EditReferenceFrameContextEnum.ForState);

            updateStateDueToFrameChange(app, handles, newFrame);
        end

        % Value changed function: utText
        function utText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.utText;
            
            % hObject    handle to utText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of utText as text
            %        str2double(get(hObject,'String')) returns contents of utText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            updateValuesInState(app, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditTwoBodyPointGUI = uifigure('Visible', 'off');
            app.lvd_EditTwoBodyPointGUI.Name = 'Edit Point';
            app.lvd_EditTwoBodyPointGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditTwoBodyPointGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditTwoBodyPointGUI_WindowKeyPressFcn, true);
            app.lvd_EditTwoBodyPointGUI.Tag = 'lvd_EditTwoBodyPointGUI';
            app.GridLayout = uigridlayout(app.lvd_EditTwoBodyPointGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Point';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Name';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4 = uigridlayout(app.uipanel2);
            app.stageName = uilabel(app.GridLayout4);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Point Name';
            app.pointNameText = uieditfield(app.GridLayout4, 'text');
            app.pointNameText.Tag = 'pointNameText';
            app.pointNameText.Value = 'POINT NAME';
            app.uipanel6 = uipanel(app.GridLayout3);
            app.uipanel6.Title = 'Orbit Parameters';
            app.uipanel6.Tag = 'uipanel6';
            app.GridLayout5 = uigridlayout(app.uipanel6);
            app.text31 = uilabel(app.GridLayout5);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Element Set';
            app.text32 = uilabel(app.GridLayout5);
            app.text32.Tag = 'text32';
            app.text32.Text = 'Frame Type';
            app.elementSetCombo = uidropdown(app.GridLayout5);
            app.elementSetCombo.Items = {'Pop-up Menu'};
            app.elementSetCombo.ValueChangedFcn = createCallbackFcn(app, @elementSetCombo_Callback, true);
            app.elementSetCombo.Tag = 'elementSetCombo';
            app.elementSetCombo.Tooltip = 'The elements used to define the state (position and velocity) of the vehicle.';
            app.elementSetCombo.Value = 'Pop-up Menu';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.setFrameOptionsButton = uibutton(app.GridLayout6, 'push');
            app.setFrameOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @setFrameOptionsButton_Callback, true);
            app.setFrameOptionsButton.Tag = 'setFrameOptionsButton';
            app.setFrameOptionsButton.Icon = 'reference_frame.png';
            app.setFrameOptionsButton.Text = 'Set Frame Options';
            app.refFrameTypeCombo = uidropdown(app.GridLayout6);
            app.refFrameTypeCombo.Items = {'Pop-up Menu'};
            app.refFrameTypeCombo.ValueChangedFcn = createCallbackFcn(app, @refFrameTypeCombo_Callback, true);
            app.refFrameTypeCombo.Tag = 'refFrameTypeCombo';
            app.refFrameTypeCombo.Tooltip = 'The type of reference frame used with the selected element set, relative to the selected central body.  Generally, use Keplerian elements with an inertial frame and geographic elements with a body-fixed frame.';
            app.refFrameTypeCombo.Value = 'Pop-up Menu';
            app.uipanel7 = uipanel(app.GridLayout3);
            app.uipanel7.Title = 'Orbit';
            app.uipanel7.Tag = 'uipanel7';
            app.GridLayout7 = uigridlayout(app.uipanel7);
            app.text33 = uilabel(app.GridLayout7);
            app.text33.Tag = 'text33';
            app.text33.Text = 'Epoch (UT)';
            app.orbit1Label = uilabel(app.GridLayout7);
            app.orbit1Label.Tag = 'orbit1Label';
            app.orbit1Label.Text = 'Orbit1';
            app.orbit2Label = uilabel(app.GridLayout7);
            app.orbit2Label.Tag = 'orbit2Label';
            app.orbit2Label.Text = 'Orbit2';
            app.orbit3Label = uilabel(app.GridLayout7);
            app.orbit3Label.Tag = 'orbit3Label';
            app.orbit3Label.Text = 'Orbit3';
            app.orbit4Label = uilabel(app.GridLayout7);
            app.orbit4Label.Tag = 'orbit4Label';
            app.orbit4Label.Text = 'Orbit4';
            app.orbit5Label = uilabel(app.GridLayout7);
            app.orbit5Label.Tag = 'orbit5Label';
            app.orbit5Label.Text = 'Orbit5';
            app.orbit6Label = uilabel(app.GridLayout7);
            app.orbit6Label.Tag = 'orbit6Label';
            app.orbit6Label.Text = 'Orbit6';
            app.utText = uieditfield(app.GridLayout7, 'text');
            app.utText.ValueChangedFcn = createCallbackFcn(app, @utText_Callback, true);
            app.utText.Tag = 'utText';
            app.utText.Value = '0.0';
            app.orbit1Text = uieditfield(app.GridLayout7, 'text');
            app.orbit1Text.ValueChangedFcn = createCallbackFcn(app, @orbit1Text_Callback, true);
            app.orbit1Text.Tag = 'orbit1Text';
            app.orbit1Text.Value = '0.0';
            app.orbit2Text = uieditfield(app.GridLayout7, 'text');
            app.orbit2Text.ValueChangedFcn = createCallbackFcn(app, @orbit2Text_Callback, true);
            app.orbit2Text.Tag = 'orbit2Text';
            app.orbit2Text.Value = '0.0';
            app.orbit3Text = uieditfield(app.GridLayout7, 'text');
            app.orbit3Text.ValueChangedFcn = createCallbackFcn(app, @orbit3Text_Callback, true);
            app.orbit3Text.Tag = 'orbit3Text';
            app.orbit3Text.Value = '0.0';
            app.orbit4Text = uieditfield(app.GridLayout7, 'text');
            app.orbit4Text.ValueChangedFcn = createCallbackFcn(app, @orbit4Text_Callback, true);
            app.orbit4Text.Tag = 'orbit4Text';
            app.orbit4Text.Value = '0.0';
            app.orbit5Text = uieditfield(app.GridLayout7, 'text');
            app.orbit5Text.ValueChangedFcn = createCallbackFcn(app, @orbit5Text_Callback, true);
            app.orbit5Text.Tag = 'orbit5Text';
            app.orbit5Text.Value = '0.0';
            app.orbit6Text = uieditfield(app.GridLayout7, 'text');
            app.orbit6Text.ValueChangedFcn = createCallbackFcn(app, @orbit6Text_Callback, true);
            app.orbit6Text.Tag = 'orbit6Text';
            app.orbit6Text.Value = '0.0';
            app.text34 = uilabel(app.GridLayout7);
            app.text34.Tag = 'text34';
            app.text34.Text = 'sec';
            app.orbit1UnitLabel = uilabel(app.GridLayout7);
            app.orbit1UnitLabel.Tag = 'orbit1UnitLabel';
            app.orbit1UnitLabel.Text = '';
            app.orbit2UnitLabel = uilabel(app.GridLayout7);
            app.orbit2UnitLabel.Tag = 'orbit2UnitLabel';
            app.orbit2UnitLabel.Text = '';
            app.orbit3UnitLabel = uilabel(app.GridLayout7);
            app.orbit3UnitLabel.Tag = 'orbit3UnitLabel';
            app.orbit3UnitLabel.Text = '';
            app.orbit4UnitLabel = uilabel(app.GridLayout7);
            app.orbit4UnitLabel.Tag = 'orbit4UnitLabel';
            app.orbit4UnitLabel.Text = '';
            app.orbit5UnitLabel = uilabel(app.GridLayout7);
            app.orbit5UnitLabel.Tag = 'orbit5UnitLabel';
            app.orbit5UnitLabel.Text = '';
            app.orbit6UnitLabel = uilabel(app.GridLayout7);
            app.orbit6UnitLabel.Tag = 'orbit6UnitLabel';
            app.orbit6UnitLabel.Text = '';
            app.uipanel5 = uipanel(app.GridLayout3);
            app.uipanel5.Title = 'Display';
            app.uipanel5.Tag = 'uipanel5';
            app.GridLayout8 = uigridlayout(app.uipanel5);
            app.text29 = uilabel(app.GridLayout8);
            app.text29.Tag = 'text29';
            app.text29.Text = 'Point Marker';
            app.pointColorCombo = uidropdown(app.GridLayout8);
            app.pointColorCombo.Items = {'Pop-up Menu'};
            app.pointColorCombo.Tag = 'pointColorCombo';
            app.pointColorCombo.Tooltip = 'The color of the point marker on the display.';
            app.pointColorCombo.Value = 'Pop-up Menu';
            app.pointMarkerShapeCombo = uidropdown(app.GridLayout8);
            app.pointMarkerShapeCombo.Items = {'Pop-up Menu'};
            app.pointMarkerShapeCombo.Tag = 'pointMarkerShapeCombo';
            app.pointMarkerShapeCombo.Tooltip = 'The shape of the point marker on the display.';
            app.pointMarkerShapeCombo.Value = 'Pop-up Menu';
            app.text30 = uilabel(app.GridLayout8);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Point Track Line ';
            app.pointLineColorCombo = uidropdown(app.GridLayout8);
            app.pointLineColorCombo.Items = {'Pop-up Menu'};
            app.pointLineColorCombo.Tag = 'pointLineColorCombo';
            app.pointLineColorCombo.Tooltip = 'The color of the point track line on the display.';
            app.pointLineColorCombo.Value = 'Pop-up Menu';
            app.pointLineSpecCombo = uidropdown(app.GridLayout8);
            app.pointLineSpecCombo.Items = {'Pop-up Menu'};
            app.pointLineSpecCombo.Tag = 'pointLineSpecCombo';
            app.pointLineSpecCombo.Tooltip = 'The style of the line of the point track line on the display.';
            app.pointLineSpecCombo.Value = 'Pop-up Menu';
            app.dispTrajCheckbox = uicheckbox(app.GridLayout8);
            app.dispTrajCheckbox.Tag = 'dispTrajCheckbox';
            app.dispTrajCheckbox.Text = 'Display Trajectory';
            app.Image = uiimage(app.GridLayout8);
            app.Image.ImageSource = 'color_wheel.png';
            app.Image2 = uiimage(app.GridLayout8);
            app.Image2.ImageSource = 'line.png';
            app.epochContextMenu = uicontextmenu(app.lvd_EditTwoBodyPointGUI);
            app.epochContextMenu.Tag = 'epochContextMenu';
            app.enterUTAsDateTimeContextMenu = uimenu(app.epochContextMenu);
            app.enterUTAsDateTimeContextMenu.MenuSelectedFcn = createCallbackFcn(app, @enterUTAsDateTimeContextMenu_Callback, true);
            app.enterUTAsDateTimeContextMenu.Text = 'Enter UT As Date/Time';
            app.enterUTAsDateTimeContextMenu.Tag = 'enterUTAsDateTimeContextMenu';
            % Assign app.epochContextMenu
            app.text33.ContextMenu = app.epochContextMenu;
            app.utText.ContextMenu = app.epochContextMenu;
            app.text34.ContextMenu = app.epochContextMenu;
            app.orbitPanelContextMenu = uicontextmenu(app.lvd_EditTwoBodyPointGUI);
            app.orbitPanelContextMenu.Tag = 'orbitPanelContextMenu';
            app.getOrbitFromSFSFileContextMenu = uimenu(app.orbitPanelContextMenu);
            app.getOrbitFromSFSFileContextMenu.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromSFSFileContextMenu_Callback, true);
            app.getOrbitFromSFSFileContextMenu.Text = 'Get Orbit From SFS File';
            app.getOrbitFromSFSFileContextMenu.Tag = 'getOrbitFromSFSFileContextMenu';
            app.getOrbitFromKSPTOTConnectContextMenu = uimenu(app.orbitPanelContextMenu);
            app.getOrbitFromKSPTOTConnectContextMenu.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromKSPTOTConnectContextMenu_Callback, true);
            app.getOrbitFromKSPTOTConnectContextMenu.Text = 'Get Orbit From KSP';
            app.getOrbitFromKSPTOTConnectContextMenu.Tag = 'getOrbitFromKSPTOTConnectContextMenu';
            app.getOrbitFromKSPActiveVesselMenu = uimenu(app.orbitPanelContextMenu);
            app.getOrbitFromKSPActiveVesselMenu.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromKSPActiveVesselMenu_Callback, true);
            app.getOrbitFromKSPActiveVesselMenu.Text = 'Get Orbit From KSP (Active Vessel)';
            app.getOrbitFromKSPActiveVesselMenu.Tag = 'getOrbitFromKSPActiveVesselMenu';
            app.copyOrbitToClipboardMenu = uimenu(app.orbitPanelContextMenu);
            app.copyOrbitToClipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @copyOrbitToClipboardMenu_Callback, true);
            app.copyOrbitToClipboardMenu.Text = 'Copy Orbit to Clipboard';
            app.copyOrbitToClipboardMenu.Tag = 'copyOrbitToClipboardMenu';
            app.pasteOrbitFromClipboardMenu = uimenu(app.orbitPanelContextMenu);
            app.pasteOrbitFromClipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @pasteOrbitFromClipboardMenu_Callback, true);
            app.pasteOrbitFromClipboardMenu.Text = 'Paste Orbit from Clipboard';
            app.pasteOrbitFromClipboardMenu.Tag = 'pasteOrbitFromClipboardMenu';
            % Assign app.orbitPanelContextMenu
            app.uipanel7.ContextMenu = app.orbitPanelContextMenu;
            app.orbit1Label.ContextMenu = app.orbitPanelContextMenu;
            app.orbit2Label.ContextMenu = app.orbitPanelContextMenu;
            app.orbit3Label.ContextMenu = app.orbitPanelContextMenu;
            app.orbit4Label.ContextMenu = app.orbitPanelContextMenu;
            app.orbit5Label.ContextMenu = app.orbitPanelContextMenu;
            app.orbit6Label.ContextMenu = app.orbitPanelContextMenu;
            app.orbit1Text.ContextMenu = app.orbitPanelContextMenu;
            app.orbit2Text.ContextMenu = app.orbitPanelContextMenu;
            app.orbit3Text.ContextMenu = app.orbitPanelContextMenu;
            app.orbit4Text.ContextMenu = app.orbitPanelContextMenu;
            app.orbit5Text.ContextMenu = app.orbitPanelContextMenu;
            app.orbit6Text.ContextMenu = app.orbitPanelContextMenu;
            app.orbit1UnitLabel.ContextMenu = app.orbitPanelContextMenu;
            app.orbit2UnitLabel.ContextMenu = app.orbitPanelContextMenu;
            app.orbit3UnitLabel.ContextMenu = app.orbitPanelContextMenu;
            app.orbit4UnitLabel.ContextMenu = app.orbitPanelContextMenu;
            app.orbit5UnitLabel.ContextMenu = app.orbitPanelContextMenu;
            app.orbit6UnitLabel.ContextMenu = app.orbitPanelContextMenu;
            % Show the figure after all components are created
            app.lvd_EditTwoBodyPointGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditTwoBodyPointGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditTwoBodyPointGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditTwoBodyPointGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditTwoBodyPointGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditTwoBodyPointGUI)
        end
    end
end