classdef lvd_EditVehiclePointGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditVehiclePointGUI  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        uipanel1                 matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        uipanel2                 matlab.ui.container.Panel
        GridLayout4              matlab.ui.container.GridLayout
        pointNameText            matlab.ui.control.EditField
        stageName                matlab.ui.control.Label
        GridLayout2              matlab.ui.container.GridLayout
        cancelButton             matlab.ui.control.Button
        saveAndCloseButton       matlab.ui.control.Button
        text2                    matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, point)
            set(handles.pointNameText,'String',point.getName());
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            if(isempty(strtrim(handles.pointNameText.String)))
                errMsg = {'Point name must contain more than white space and must not be empty.'};
            end
        end


        function saveAndClose(app, handles)
            point = getappdata(handles.lvd_EditVehiclePointGUI, 'point');

            point.setName(handles.pointNameText.String);

            app.output.output = {true};
            close(handles.lvd_EditVehiclePointGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditVehiclePointGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditVehiclePointGUI (see VARARGIN)

            % Choose default command line output for lvd_EditVehiclePointGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            point = varargin{1};
            setappdata(hObject, 'point', point);

            app.output = varargin{2};
            
            populateGUI(app, handles, point);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditVehiclePointGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditVehiclePointGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditVehiclePointGUI);
        end

        % Window key press function: lvd_EditVehiclePointGUI
        function lvd_EditVehiclePointGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditVehiclePointGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditVehiclePointGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
%                 uiresume(handles.lvd_EditVehiclePointGUI);
                app.saveAndClose(handles);
            else
%                 msgbox(errMsg,'Invalid Point Inputs','error');
                uialert(app.lvd_EditVehiclePointGUI, errMsg, 'Invalid Point Inputs', "Icon","error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditVehiclePointGUI = uifigure('Visible', 'off');
            app.lvd_EditVehiclePointGUI.Name = 'Edit Point';
            app.lvd_EditVehiclePointGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditVehiclePointGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditVehiclePointGUI_WindowKeyPressFcn, true);
            app.lvd_EditVehiclePointGUI.Tag = 'lvd_EditVehiclePointGUI';
            app.GridLayout = uigridlayout(app.lvd_EditVehiclePointGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Point';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Name';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4 = uigridlayout(app.uipanel2);
            app.stageName = uilabel(app.GridLayout4);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Point Name';
            app.pointNameText = uieditfield(app.GridLayout4, 'text');
            app.pointNameText.Tag = 'pointNameText';
            app.pointNameText.Value = 'POINT NAME';
            % Show the figure after all components are created
            app.lvd_EditVehiclePointGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditVehiclePointGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditVehiclePointGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditVehiclePointGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditVehiclePointGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditVehiclePointGUI)
        end
    end
end