classdef lvd_EditCoordSysOriginRefFrameGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditCoordSysOriginRefFrameGUI  matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        uipanel1            matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        uipanel6            matlab.ui.container.Panel
        GridLayout6         matlab.ui.container.GridLayout
        GridLayout8         matlab.ui.container.GridLayout
        text39              matlab.ui.control.Label
        scaleFactorText     matlab.ui.control.EditField
        text38              matlab.ui.control.Label
        GridLayout7         matlab.ui.container.GridLayout
        zLineWidthText      matlab.ui.control.EditField
        zLineStyleCombo     matlab.ui.control.DropDown
        zColorCombo         matlab.ui.control.DropDown
        yLineWidthText      matlab.ui.control.EditField
        yLineStyleCombo     matlab.ui.control.DropDown
        yColorCombo         matlab.ui.control.DropDown
        xLineWidthText      matlab.ui.control.EditField
        xLineStyleCombo     matlab.ui.control.DropDown
        xColorCombo         matlab.ui.control.DropDown
        text37              matlab.ui.control.Label
        text36              matlab.ui.control.Label
        text35              matlab.ui.control.Label
        text34              matlab.ui.control.Label
        text33              matlab.ui.control.Label
        text32              matlab.ui.control.Label
        uipanel3            matlab.ui.container.Panel
        GridLayout5         matlab.ui.container.GridLayout
        coordSysCombo       matlab.ui.control.DropDown
        frameOriginCombo    matlab.ui.control.DropDown
        text31              matlab.ui.control.Label
        text5               matlab.ui.control.Label
        uipanel2            matlab.ui.container.Panel
        GridLayout4         matlab.ui.container.GridLayout
        refFrameNameText    matlab.ui.control.EditField
        stageName           matlab.ui.control.Label
        GridLayout2         matlab.ui.container.GridLayout
        cancelButton        matlab.ui.control.Button
        saveAndCloseButton  matlab.ui.control.Button
        text2               matlab.ui.control.Label
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, lvdData, refFrame)
            set(handles.refFrameNameText,'String',refFrame.getName());

            pointsListBoxStr = lvdData.geometry.points.getListboxStr();
            handles.frameOriginCombo.String = pointsListBoxStr;
            handles.frameOriginCombo.Value = lvdData.geometry.points.getIndsForPoints(refFrame.origin);

            coordSysListBoxStr = lvdData.geometry.coordSyses.getListboxStr();
            handles.coordSysCombo.String = coordSysListBoxStr;
            handles.coordSysCombo.Value = lvdData.geometry.coordSyses.getIndsForCoordSyses(refFrame.coordSys);

            handles.xColorCombo.String = ColorSpecEnum.getListboxStr();
            handles.xColorCombo.Value = ColorSpecEnum.getIndForName(refFrame.xAxisColor.name);

            handles.yColorCombo.String = ColorSpecEnum.getListboxStr();
            handles.yColorCombo.Value = ColorSpecEnum.getIndForName(refFrame.yAxisColor.name);

            handles.zColorCombo.String = ColorSpecEnum.getListboxStr();
            handles.zColorCombo.Value = ColorSpecEnum.getIndForName(refFrame.zAxisColor.name);

            handles.xLineStyleCombo.String = LineSpecEnum.getListboxStr();
            handles.xLineStyleCombo.Value = LineSpecEnum.getIndForName(refFrame.xAxisLineSpec.name);

            handles.yLineStyleCombo.String = LineSpecEnum.getListboxStr();
            handles.yLineStyleCombo.Value = LineSpecEnum.getIndForName(refFrame.yAxisLineSpec.name);

            handles.zLineStyleCombo.String = LineSpecEnum.getListboxStr();
            handles.zLineStyleCombo.Value = LineSpecEnum.getIndForName(refFrame.zAxisLineSpec.name);

            handles.xLineWidthText.String = fullAccNum2Str(refFrame.xAxisLineWidth);
            handles.yLineWidthText.String = fullAccNum2Str(refFrame.yAxisLineWidth);
            handles.zLineWidthText.String = fullAccNum2Str(refFrame.zAxisLineWidth);

            handles.scaleFactorText.String = fullAccNum2Str(refFrame.scaleFactor);
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            if(isempty(strtrim(handles.refFrameNameText.String)))
                errMsg{end+1} = 'Reference frame name must contain more than white space and must not be empty.';
            end

            val = str2double(get(handles.xLineWidthText,'String'));
            enteredStr = get(handles.xLineWidthText,'String');
            numberName = 'X Axis Line Width';
            lb = 0.01;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);

            val = str2double(get(handles.yLineWidthText,'String'));
            enteredStr = get(handles.yLineWidthText,'String');
            numberName = 'Y Axis Line Width';
            lb = 0.01;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);

            val = str2double(get(handles.zLineWidthText,'String'));
            enteredStr = get(handles.zLineWidthText,'String');
            numberName = 'Z Axis Line Width';
            lb = 0.01;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);

            val = str2double(get(handles.scaleFactorText,'String'));
            enteredStr = get(handles.scaleFactorText,'String');
            numberName = 'Axes Scale Factor';
            lb = 0.001;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
        end

        
        function saveAndClose(app, handles)
            lvdData = getappdata(handles.lvd_EditCoordSysOriginRefFrameGUI, 'lvdData');
            refFrame = getappdata(handles.lvd_EditCoordSysOriginRefFrameGUI, 'refFrame');
            
            refFrame.setName(handles.refFrameNameText.String);
            
            refFrame.origin = lvdData.geometry.points.getPointsForInds(handles.frameOriginCombo.Value);
            refFrame.coordSys = lvdData.geometry.coordSyses.getCoordSysesForInds(handles.coordSysCombo.Value);
            
            str = handles.xColorCombo.String{handles.xColorCombo.Value};
            refFrame.xAxisColor = ColorSpecEnum.getEnumForListboxStr(str);
            
            str = handles.xColorCombo.String{handles.yColorCombo.Value};
            refFrame.yAxisColor = ColorSpecEnum.getEnumForListboxStr(str);
            
            str = handles.xColorCombo.String{handles.zColorCombo.Value};
            refFrame.zAxisColor = ColorSpecEnum.getEnumForListboxStr(str);
            
            str = handles.xLineStyleCombo.String{handles.xLineStyleCombo.Value};
            refFrame.xAxisLineSpec = LineSpecEnum.getEnumForListboxStr(str);
            
            str = handles.xLineStyleCombo.String{handles.yLineStyleCombo.Value};
            refFrame.yAxisLineSpec = LineSpecEnum.getEnumForListboxStr(str);
            
            str = handles.xLineStyleCombo.String{handles.zLineStyleCombo.Value};
            refFrame.zAxisLineSpec = LineSpecEnum.getEnumForListboxStr(str);
            
            refFrame.xAxisLineWidth = str2double(handles.xLineWidthText.String);
            refFrame.yAxisLineWidth = str2double(handles.yLineWidthText.String);
            refFrame.zAxisLineWidth = str2double(handles.zLineWidthText.String);
            
            refFrame.scaleFactor = str2double(handles.scaleFactorText.String);
            
            app.output.output = {true};
            close(handles.lvd_EditCoordSysOriginRefFrameGUI);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditCoordSysOriginRefFrameGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditCoordSysOriginRefFrameGUI (see VARARGIN)

            % Choose default command line output for lvd_EditCoordSysOriginRefFrameGUI
            handles.output = hObject;

            centerUIFigure(hObject);

            refFrame = varargin{1};
            setappdata(hObject, 'refFrame', refFrame);

            lvdData = varargin{2};
            setappdata(hObject, 'lvdData', lvdData);
            
            app.output = varargin{3};

            populateGUI(app, handles, lvdData, refFrame);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditCoordSysOriginRefFrameGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditCoordSysOriginRefFrameGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditCoordSysOriginRefFrameGUI);
        end

        % Window key press function: 
        % lvd_EditCoordSysOriginRefFrameGUI
        function lvd_EditCoordSysOriginRefFrameGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditCoordSysOriginRefFrameGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditCoordSysOriginRefFrameGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
%                 uiresume(handles.lvd_EditCoordSysOriginRefFrameGUI);
                app.saveAndClose(handles);
            else
%                 msgbox(errMsg,'Invalid Reference Frame Inputs','error');
                uialert(app.lvd_EditCoordSysOriginRefFrameGUI, errMsg, 'Invalid Reference Frame Inputs', "Icon","error");
            end
        end

        % Value changed function: scaleFactorText
        function scaleFactorText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to scaleFactorText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of scaleFactorText as text
            %        str2double(get(hObject,'String')) returns contents of scaleFactorText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: xLineWidthText
        function xLineWidthText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to xLineWidthText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of xLineWidthText as text
            %        str2double(get(hObject,'String')) returns contents of xLineWidthText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: yLineWidthText
        function yLineWidthText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to yLineWidthText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of yLineWidthText as text
            %        str2double(get(hObject,'String')) returns contents of yLineWidthText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: zLineWidthText
        function zLineWidthText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to zLineWidthText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of zLineWidthText as text
            %        str2double(get(hObject,'String')) returns contents of zLineWidthText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditCoordSysOriginRefFrameGUI = uifigure('Visible', 'off');
            app.lvd_EditCoordSysOriginRefFrameGUI.Name = 'Edit Reference Frame';
            app.lvd_EditCoordSysOriginRefFrameGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditCoordSysOriginRefFrameGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditCoordSysOriginRefFrameGUI_WindowKeyPressFcn, true);
            app.lvd_EditCoordSysOriginRefFrameGUI.Tag = 'lvd_EditCoordSysOriginRefFrameGUI';
            app.GridLayout = uigridlayout(app.lvd_EditCoordSysOriginRefFrameGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Reference Frame';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Name';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4 = uigridlayout(app.uipanel2);
            app.stageName = uilabel(app.GridLayout4);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Ref. Frame Name';
            app.refFrameNameText = uieditfield(app.GridLayout4, 'text');
            app.refFrameNameText.Tag = 'refFrameNameText';
            app.refFrameNameText.Value = 'POINT NAME';
            app.uipanel3 = uipanel(app.GridLayout3);
            app.uipanel3.Title = 'Reference Frame';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout5 = uigridlayout(app.uipanel3);
            app.text5 = uilabel(app.GridLayout5);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Ref. Frame Origin';
            app.text31 = uilabel(app.GridLayout5);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Coordinate System';
            app.frameOriginCombo = uidropdown(app.GridLayout5);
            app.frameOriginCombo.Items = {'Pop-up Menu'};
            app.frameOriginCombo.Tag = 'frameOriginCombo';
            app.frameOriginCombo.Tooltip = 'This point is the origin of the reference frame.';
            app.frameOriginCombo.Value = 'Pop-up Menu';
            app.coordSysCombo = uidropdown(app.GridLayout5);
            app.coordSysCombo.Items = {'Pop-up Menu'};
            app.coordSysCombo.Tag = 'coordSysCombo';
            app.coordSysCombo.Tooltip = 'This is the coordiate system of the reference frame.';
            app.coordSysCombo.Value = 'Pop-up Menu';
            app.text5 = uilabel(app.GridLayout5);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Ref. Frame Origin';
            app.frameOriginCombo = uidropdown(app.GridLayout5);
            app.frameOriginCombo.Items = {'Pop-up Menu'};
            app.frameOriginCombo.Tag = 'frameOriginCombo';
            app.frameOriginCombo.Tooltip = 'This point is the origin of the reference frame.';
            app.frameOriginCombo.Value = 'Pop-up Menu';
            app.text31 = uilabel(app.GridLayout5);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Coordinate System';
            app.coordSysCombo = uidropdown(app.GridLayout5);
            app.coordSysCombo.Items = {'Pop-up Menu'};
            app.coordSysCombo.Tag = 'coordSysCombo';
            app.coordSysCombo.Tooltip = 'This is the coordiate system of the reference frame.';
            app.coordSysCombo.Value = 'Pop-up Menu';
            app.uipanel6 = uipanel(app.GridLayout3);
            app.uipanel6.Title = 'Display';
            app.uipanel6.Tag = 'uipanel6';
            app.GridLayout6 = uigridlayout(app.uipanel6);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.text32 = uilabel(app.GridLayout7);
            app.text32.Tag = 'text32';
            app.text32.Text = 'X Axis';
            app.text33 = uilabel(app.GridLayout7);
            app.text33.Tag = 'text33';
            app.text33.Text = 'Y Axis';
            app.text34 = uilabel(app.GridLayout7);
            app.text34.Tag = 'text34';
            app.text34.Text = 'Z Axis';
            app.text35 = uilabel(app.GridLayout7);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Color';
            app.text36 = uilabel(app.GridLayout7);
            app.text36.Tag = 'text36';
            app.text36.Text = 'Line Style';
            app.text37 = uilabel(app.GridLayout7);
            app.text37.Tag = 'text37';
            app.text37.Text = 'Line Width';
            app.xColorCombo = uidropdown(app.GridLayout7);
            app.xColorCombo.Items = {'Pop-up Menu'};
            app.xColorCombo.Tag = 'xColorCombo';
            app.xColorCombo.Value = 'Pop-up Menu';
            app.xLineStyleCombo = uidropdown(app.GridLayout7);
            app.xLineStyleCombo.Items = {'Pop-up Menu'};
            app.xLineStyleCombo.Tag = 'xLineStyleCombo';
            app.xLineStyleCombo.Value = 'Pop-up Menu';
            app.xLineWidthText = uieditfield(app.GridLayout7, 'text');
            app.xLineWidthText.ValueChangedFcn = createCallbackFcn(app, @xLineWidthText_Callback, true);
            app.xLineWidthText.Tag = 'xLineWidthText';
            app.xLineWidthText.Value = 'Edit Text';
            app.yColorCombo = uidropdown(app.GridLayout7);
            app.yColorCombo.Items = {'Pop-up Menu'};
            app.yColorCombo.Tag = 'yColorCombo';
            app.yColorCombo.Value = 'Pop-up Menu';
            app.yLineStyleCombo = uidropdown(app.GridLayout7);
            app.yLineStyleCombo.Items = {'Pop-up Menu'};
            app.yLineStyleCombo.Tag = 'yLineStyleCombo';
            app.yLineStyleCombo.Value = 'Pop-up Menu';
            app.yLineWidthText = uieditfield(app.GridLayout7, 'text');
            app.yLineWidthText.ValueChangedFcn = createCallbackFcn(app, @yLineWidthText_Callback, true);
            app.yLineWidthText.Tag = 'yLineWidthText';
            app.yLineWidthText.Value = 'Edit Text';
            app.zColorCombo = uidropdown(app.GridLayout7);
            app.zColorCombo.Items = {'Pop-up Menu'};
            app.zColorCombo.Tag = 'zColorCombo';
            app.zColorCombo.Value = 'Pop-up Menu';
            app.zLineStyleCombo = uidropdown(app.GridLayout7);
            app.zLineStyleCombo.Items = {'Pop-up Menu'};
            app.zLineStyleCombo.Tag = 'zLineStyleCombo';
            app.zLineStyleCombo.Value = 'Pop-up Menu';
            app.zLineWidthText = uieditfield(app.GridLayout7, 'text');
            app.zLineWidthText.ValueChangedFcn = createCallbackFcn(app, @zLineWidthText_Callback, true);
            app.zLineWidthText.Tag = 'zLineWidthText';
            app.zLineWidthText.Value = 'Edit Text';
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.text38 = uilabel(app.GridLayout8);
            app.text38.Tag = 'text38';
            app.text38.Text = 'Scale Factor';
            app.scaleFactorText = uieditfield(app.GridLayout8, 'text');
            app.scaleFactorText.ValueChangedFcn = createCallbackFcn(app, @scaleFactorText_Callback, true);
            app.scaleFactorText.Tag = 'scaleFactorText';
            app.scaleFactorText.Tooltip = 'The length of the axes of the reference frame, in km.';
            app.scaleFactorText.Value = 'Edit Text';
            app.text39 = uilabel(app.GridLayout8);
            app.text39.Tag = 'text39';
            app.text39.Text = 'km';
            app.text32 = uilabel(app.GridLayout7);
            app.text32.Tag = 'text32';
            app.text32.Text = 'X Axis';
            app.xColorCombo = uidropdown(app.GridLayout7);
            app.xColorCombo.Items = {'Pop-up Menu'};
            app.xColorCombo.Tag = 'xColorCombo';
            app.xColorCombo.Value = 'Pop-up Menu';
            app.xLineStyleCombo = uidropdown(app.GridLayout7);
            app.xLineStyleCombo.Items = {'Pop-up Menu'};
            app.xLineStyleCombo.Tag = 'xLineStyleCombo';
            app.xLineStyleCombo.Value = 'Pop-up Menu';
            app.xLineWidthText = uieditfield(app.GridLayout7, 'text');
            app.xLineWidthText.ValueChangedFcn = createCallbackFcn(app, @xLineWidthText_Callback, true);
            app.xLineWidthText.Tag = 'xLineWidthText';
            app.xLineWidthText.Value = 'Edit Text';
            app.text33 = uilabel(app.GridLayout7);
            app.text33.Tag = 'text33';
            app.text33.Text = 'Y Axis';
            app.yColorCombo = uidropdown(app.GridLayout7);
            app.yColorCombo.Items = {'Pop-up Menu'};
            app.yColorCombo.Tag = 'yColorCombo';
            app.yColorCombo.Value = 'Pop-up Menu';
            app.yLineStyleCombo = uidropdown(app.GridLayout7);
            app.yLineStyleCombo.Items = {'Pop-up Menu'};
            app.yLineStyleCombo.Tag = 'yLineStyleCombo';
            app.yLineStyleCombo.Value = 'Pop-up Menu';
            app.yLineWidthText = uieditfield(app.GridLayout7, 'text');
            app.yLineWidthText.ValueChangedFcn = createCallbackFcn(app, @yLineWidthText_Callback, true);
            app.yLineWidthText.Tag = 'yLineWidthText';
            app.yLineWidthText.Value = 'Edit Text';
            app.text34 = uilabel(app.GridLayout7);
            app.text34.Tag = 'text34';
            app.text34.Text = 'Z Axis';
            app.zColorCombo = uidropdown(app.GridLayout7);
            app.zColorCombo.Items = {'Pop-up Menu'};
            app.zColorCombo.Tag = 'zColorCombo';
            app.zColorCombo.Value = 'Pop-up Menu';
            app.zLineStyleCombo = uidropdown(app.GridLayout7);
            app.zLineStyleCombo.Items = {'Pop-up Menu'};
            app.zLineStyleCombo.Tag = 'zLineStyleCombo';
            app.zLineStyleCombo.Value = 'Pop-up Menu';
            app.zLineWidthText = uieditfield(app.GridLayout7, 'text');
            app.zLineWidthText.ValueChangedFcn = createCallbackFcn(app, @zLineWidthText_Callback, true);
            app.zLineWidthText.Tag = 'zLineWidthText';
            app.zLineWidthText.Value = 'Edit Text';
            app.text35 = uilabel(app.GridLayout7);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Color';
            app.text36 = uilabel(app.GridLayout7);
            app.text36.Tag = 'text36';
            app.text36.Text = 'Line Style';
            app.text37 = uilabel(app.GridLayout7);
            app.text37.Tag = 'text37';
            app.text37.Text = 'Line Width';
            app.text38 = uilabel(app.GridLayout8);
            app.text38.Tag = 'text38';
            app.text38.Text = 'Scale Factor';
            app.scaleFactorText = uieditfield(app.GridLayout8, 'text');
            app.scaleFactorText.ValueChangedFcn = createCallbackFcn(app, @scaleFactorText_Callback, true);
            app.scaleFactorText.Tag = 'scaleFactorText';
            app.scaleFactorText.Tooltip = 'The length of the axes of the reference frame, in km.';
            app.scaleFactorText.Value = 'Edit Text';
            app.text39 = uilabel(app.GridLayout8);
            app.text39.Tag = 'text39';
            app.text39.Text = 'km';
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Name';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4 = uigridlayout(app.uipanel2);
            app.stageName = uilabel(app.GridLayout4);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Ref. Frame Name';
            app.refFrameNameText = uieditfield(app.GridLayout4, 'text');
            app.refFrameNameText.Tag = 'refFrameNameText';
            app.refFrameNameText.Value = 'POINT NAME';
            app.uipanel3 = uipanel(app.GridLayout3);
            app.uipanel3.Title = 'Reference Frame';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout5 = uigridlayout(app.uipanel3);
            app.text5 = uilabel(app.GridLayout5);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Ref. Frame Origin';
            app.frameOriginCombo = uidropdown(app.GridLayout5);
            app.frameOriginCombo.Items = {'Pop-up Menu'};
            app.frameOriginCombo.Tag = 'frameOriginCombo';
            app.frameOriginCombo.Tooltip = 'This point is the origin of the reference frame.';
            app.frameOriginCombo.Value = 'Pop-up Menu';
            app.text31 = uilabel(app.GridLayout5);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Coordinate System';
            app.coordSysCombo = uidropdown(app.GridLayout5);
            app.coordSysCombo.Items = {'Pop-up Menu'};
            app.coordSysCombo.Tag = 'coordSysCombo';
            app.coordSysCombo.Tooltip = 'This is the coordiate system of the reference frame.';
            app.coordSysCombo.Value = 'Pop-up Menu';
            app.uipanel6 = uipanel(app.GridLayout3);
            app.uipanel6.Title = 'Display';
            app.uipanel6.Tag = 'uipanel6';
            app.GridLayout6 = uigridlayout(app.uipanel6);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.text32 = uilabel(app.GridLayout7);
            app.text32.Tag = 'text32';
            app.text32.Text = 'X Axis';
            app.xColorCombo = uidropdown(app.GridLayout7);
            app.xColorCombo.Items = {'Pop-up Menu'};
            app.xColorCombo.Tag = 'xColorCombo';
            app.xColorCombo.Value = 'Pop-up Menu';
            app.xLineStyleCombo = uidropdown(app.GridLayout7);
            app.xLineStyleCombo.Items = {'Pop-up Menu'};
            app.xLineStyleCombo.Tag = 'xLineStyleCombo';
            app.xLineStyleCombo.Value = 'Pop-up Menu';
            app.xLineWidthText = uieditfield(app.GridLayout7, 'text');
            app.xLineWidthText.ValueChangedFcn = createCallbackFcn(app, @xLineWidthText_Callback, true);
            app.xLineWidthText.Tag = 'xLineWidthText';
            app.xLineWidthText.Value = 'Edit Text';
            app.text33 = uilabel(app.GridLayout7);
            app.text33.Tag = 'text33';
            app.text33.Text = 'Y Axis';
            app.yColorCombo = uidropdown(app.GridLayout7);
            app.yColorCombo.Items = {'Pop-up Menu'};
            app.yColorCombo.Tag = 'yColorCombo';
            app.yColorCombo.Value = 'Pop-up Menu';
            app.yLineStyleCombo = uidropdown(app.GridLayout7);
            app.yLineStyleCombo.Items = {'Pop-up Menu'};
            app.yLineStyleCombo.Tag = 'yLineStyleCombo';
            app.yLineStyleCombo.Value = 'Pop-up Menu';
            app.yLineWidthText = uieditfield(app.GridLayout7, 'text');
            app.yLineWidthText.ValueChangedFcn = createCallbackFcn(app, @yLineWidthText_Callback, true);
            app.yLineWidthText.Tag = 'yLineWidthText';
            app.yLineWidthText.Value = 'Edit Text';
            app.text34 = uilabel(app.GridLayout7);
            app.text34.Tag = 'text34';
            app.text34.Text = 'Z Axis';
            app.zColorCombo = uidropdown(app.GridLayout7);
            app.zColorCombo.Items = {'Pop-up Menu'};
            app.zColorCombo.Tag = 'zColorCombo';
            app.zColorCombo.Value = 'Pop-up Menu';
            app.zLineStyleCombo = uidropdown(app.GridLayout7);
            app.zLineStyleCombo.Items = {'Pop-up Menu'};
            app.zLineStyleCombo.Tag = 'zLineStyleCombo';
            app.zLineStyleCombo.Value = 'Pop-up Menu';
            app.zLineWidthText = uieditfield(app.GridLayout7, 'text');
            app.zLineWidthText.ValueChangedFcn = createCallbackFcn(app, @zLineWidthText_Callback, true);
            app.zLineWidthText.Tag = 'zLineWidthText';
            app.zLineWidthText.Value = 'Edit Text';
            app.text35 = uilabel(app.GridLayout7);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Color';
            app.text36 = uilabel(app.GridLayout7);
            app.text36.Tag = 'text36';
            app.text36.Text = 'Line Style';
            app.text37 = uilabel(app.GridLayout7);
            app.text37.Tag = 'text37';
            app.text37.Text = 'Line Width';
            app.text38 = uilabel(app.GridLayout8);
            app.text38.Tag = 'text38';
            app.text38.Text = 'Scale Factor';
            app.scaleFactorText = uieditfield(app.GridLayout8, 'text');
            app.scaleFactorText.ValueChangedFcn = createCallbackFcn(app, @scaleFactorText_Callback, true);
            app.scaleFactorText.Tag = 'scaleFactorText';
            app.scaleFactorText.Tooltip = 'The length of the axes of the reference frame, in km.';
            app.scaleFactorText.Value = 'Edit Text';
            app.text39 = uilabel(app.GridLayout8);
            app.text39.Tag = 'text39';
            app.text39.Text = 'km';
            % Show the figure after all components are created
            app.lvd_EditCoordSysOriginRefFrameGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditCoordSysOriginRefFrameGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditCoordSysOriginRefFrameGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditCoordSysOriginRefFrameGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditCoordSysOriginRefFrameGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditCoordSysOriginRefFrameGUI)
        end
    end
end