classdef lvd_EditGeometricRefFramesGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditGeometricRefFramesGUI  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        removeRefFrameButton           matlab.ui.control.Button
        addRefFrameButton              matlab.ui.control.Button
        refFrameListBox                matlab.ui.control.ListBox
        refFrameSearchText            matlab.ui.control.EditField
        closeButton                    matlab.ui.control.Button
        text2                          matlab.ui.control.Label
    end

    methods (Access = private)
        function populateGUI(app, handles, lvdData)
            [~, ~] = app.setListboxStr();
        end

        function [listBoxStr, refFrames] = setListboxStr(app, query)
            if(nargin < 2)
                query = app.refFrameSearchText.Value;
            end
            
            hFig = app.lvd_EditGeometricRefFramesGUI;
            currentValue = app.refFrameListBox.Value;
            hadSelection = isa(currentValue, 'AbstractGeometricRefFrame');
            if(hadSelection)
                setappdata(hFig, 'h8Selected', currentValue);
            end
            lvdData = getappdata(app.lvd_EditGeometricRefFramesGUI,'lvdData');
            [listBoxStr, refFrames] = lvdData.geometry.refFrames.getListboxStr();
            keep = lvd_filterListboxItems(query, listBoxStr);
            listBoxStr = listBoxStr(keep);
            refFrames = refFrames(keep);
            storedValue = [];
            if(isappdata(hFig, 'h8Selected'))
                storedValue = getappdata(hFig, 'h8Selected');
            end
            selectedValue = lvd_selectVisibleListValue({currentValue, storedValue}, refFrames);
            app.refFrameListBox.ItemsData = refFrames;
            app.refFrameListBox.Items = listBoxStr;
            if(not(isempty(selectedValue)))
                app.refFrameListBox.Value = selectedValue;
                setappdata(hFig, 'h8Selected', selectedValue);
            elseif(hadSelection)
                %A visible selection that was filtered out stays cleared.
            elseif(not(isempty(refFrames)))
                app.refFrameListBox.Value = refFrames(1);
                setappdata(hFig, 'h8Selected', refFrames(1));
            end
            
            num = numel(refFrames);
            if(num <= 0)
                app.removeRefFrameButton.Enable = 'off';
            else
                app.removeRefFrameButton.Enable = 'on';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditGeometricRefFramesGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditGeometricRefFramesGUI (see VARARGIN)

            % Choose default command line output for lvd_EditGeometricRefFramesGUI
            handles.output = hObject;
            
            centerUIFigure(app.lvd_EditGeometricRefFramesGUI);
            applySelectedThemeToApp(app);

            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            populateGUI(app, handles, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditGeometricRefFramesGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditGeometricRefFramesGUI);
        end

        % Button pushed function: addRefFrameButton
        function addRefFrameButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addRefFrameButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditGeometricRefFramesGUI,'lvdData');

            listboxStr = GeometricRefFrameEnum.getListBoxStr();
            out = AppDesignerGUIOutput();
            listdlgARH_App('ListString', listboxStr, ...
                'SelectionMode', 'single', ...
                'ListSize', [300 300], ...
                'InitialValue',1, ...
                'Name', 'Select New Reference Frame Type', ...
                'PromptString', 'Select the type of the reference frame to be created:', ...
                'out',out);
            Selection = out.output{1};
            ok = out.output{2};

            if(ok)
                enum = GeometricRefFrameEnum.getEnumForListboxStr(listboxStr{Selection});

                switch enum
                    case GeometricRefFrameEnum.CoordSystemOrigin
                        numPoints = lvdData.geometry.points.getNumPoints();
                        numCoordSystems = lvdData.geometry.coordSyses.getNumCoordSyses();
                        if(numPoints >= 1 && numCoordSystems >= 1)
                            origin = lvdData.geometry.points.getPointAtInd(1);
                            coordSys = lvdData.geometry.coordSyses.getCoordSysAtInd(1);
                            newRefFrame = CoordSysPointRefFrame(coordSys, origin, 'New Reference Frame', lvdData);

                        else
                            uialert(app.lvd_EditGeometricRefFramesGUI, 'Cannot create Coordinate System at Origin Reference Frame: There must be at least one geometric point and one coordinate system available.', 'Cannot Create Reference Frame',"Modal",true);
                            return;
                        end

                    otherwise
                        error('Unknown Reference Frame Type: %s', enum.name)
                end

                useTF = newRefFrame.openEditDialog();

                if(useTF)
                    lvdData.geometry.refFrames.addRefFrame(newRefFrame);
                app.refFrameSearchText.Value = '';
                [~, ~] = app.setListboxStr('');
                app.refFrameListBox.Value = newRefFrame;
                end
            end
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to closeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditGeometricRefFramesGUI);
        end

        % Window key press function: lvd_EditGeometricRefFramesGUI
        function lvd_EditGeometricRefFramesGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
 
            % hObject    handle to figure
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            switch(eventdata.Key)
                case 'return'
                    if(app.lvd_EditGeometricRefFramesGUI.CurrentObject == app.refFrameSearchText)
                        return;
                    end
                    close(handles.lvd_EditGeometricRefFramesGUI);
                case 'enter'
                    if(app.lvd_EditGeometricRefFramesGUI.CurrentObject == app.refFrameSearchText)
                        return;
                    end
                    close(handles.lvd_EditGeometricRefFramesGUI);
                case 'escape'
                    close(handles.lvd_EditGeometricRefFramesGUI);
            end
        end

        % Value changed function: refFrameListBox
        function refFrameListBox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to refFrameListBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns refFrameListBox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from refFrameListBox
            lvdData = getappdata(app.lvd_EditGeometricRefFramesGUI,'lvdData');
            if(strcmpi(get(app.lvd_EditGeometricRefFramesGUI,'SelectionType'),'open') && ...
                    lvdData.geometry.refFrames.getNumRefFrames() > 0)
                selRefFrame = app.refFrameListBox.Value;
                selRefFrame.openEditDialog();

                [~, ~] = app.setListboxStr();
            end
        end

        % Button pushed function: removeRefFrameButton
        function removeRefFrameButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeRefFrameButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditGeometricRefFramesGUI,'lvdData');

            oldInd = app.refFrameListBox.ValueIndex;
            selRefFrame = app.refFrameListBox.Value;

            tf = selRefFrame.isInUse(lvdData);

            if(tf == false)
                lvdData.geometry.refFrames.removeRefFrame(selRefFrame);

                lvdData.viewSettings.removeGeoRefFrameFromViewProfiles(selRefFrame);

                [~, visibleObjs] = app.setListboxStr(app.refFrameSearchText.Value);
                if(not(isempty(visibleObjs)))
                app.refFrameListBox.Value = visibleObjs(min(oldInd, numel(visibleObjs)));
                end
            else
                msg = sprintf('Could not delete the geometric reference frame "%s" because it is in use elsewhere.  Remove the reference frame dependencies before attempting to delete the reference frame.', selRefFrame.getName());
%                 warndlg(msg,'Cannot Delete Reference Frame','modal');
                uialert(app.lvd_EditGeometricRefFramesGUI, msg, 'Cannot Delete Reference Frame',"Modal",true);
            end
        end

        % Window button down function: lvd_EditGeometricRefFramesGUI
        function lvd_EditGeometricRefFramesGUIWindowButtonDown(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            selType = app.lvd_EditGeometricRefFramesGUI.SelectionType;
            curObj = app.lvd_EditGeometricRefFramesGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.refFrameListBox && numel(app.refFrameListBox.ItemsData) > 0)
                    selRefFrame = app.refFrameListBox.Value;
                    selRefFrame.openEditDialog();
    
                    [~, ~] = app.setListboxStr();
                end
            end            
        end

 
        % Value changing function: refFrameSearchText
        function refFrameSearchTextValueChanging(app, event)
            [~, ~] = app.setListboxStr(event.Value);
        end
        % Value changed function: refFrameSearchText
        function refFrameSearchTextValueChanged(app, event)
            [~, ~] = app.setListboxStr(event.Value);
        end
    end
    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditGeometricRefFramesGUI = uifigure('Visible', 'off');
            app.lvd_EditGeometricRefFramesGUI.Name = 'Edit Reference Frames';
            app.lvd_EditGeometricRefFramesGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditGeometricRefFramesGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_EditGeometricRefFramesGUIWindowButtonDown, true);
            app.lvd_EditGeometricRefFramesGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditGeometricRefFramesGUI_WindowKeyPressFcn, true);
            app.lvd_EditGeometricRefFramesGUI.Tag = 'lvd_EditGeometricRefFramesGUI';
            app.GridLayout = uigridlayout(app.lvd_EditGeometricRefFramesGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Reference Frames';
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Icon = 'close_window(1).png';
            app.closeButton.Text = 'Close';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.refFrameSearchText = uieditfield(app.GridLayout2, 'text');
            app.refFrameSearchText.ValueChangingFcn = createCallbackFcn(app, @refFrameSearchTextValueChanging, true);
            app.refFrameSearchText.ValueChangedFcn = createCallbackFcn(app, @refFrameSearchTextValueChanged, true);
            app.refFrameSearchText.Tag = 'refFrameSearchText';
            app.refFrameSearchText.Tooltip = 'Type to filter the reference frames below.';
            app.refFrameSearchText.Placeholder = 'Search reference frames...';
            app.refFrameListBox = uilistbox(app.GridLayout2);
            app.refFrameListBox.Items = {'Listbox'};
            app.refFrameListBox.ValueChangedFcn = createCallbackFcn(app, @refFrameListBox_Callback, true);
            app.refFrameListBox.Tag = 'refFrameListBox';
            app.refFrameListBox.Tooltip = 'Double click an entry to edit it.';
            app.refFrameListBox.Value = 'Listbox';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.addRefFrameButton = uibutton(app.GridLayout3, 'push');
            app.addRefFrameButton.ButtonPushedFcn = createCallbackFcn(app, @addRefFrameButton_Callback, true);
            app.addRefFrameButton.Tag = 'addRefFrameButton';
            app.addRefFrameButton.Icon = 'add.png';
            app.addRefFrameButton.Text = 'Add Ref. Frame';
            app.removeRefFrameButton = uibutton(app.GridLayout3, 'push');
            app.removeRefFrameButton.ButtonPushedFcn = createCallbackFcn(app, @removeRefFrameButton_Callback, true);
            app.removeRefFrameButton.Tag = 'removeRefFrameButton';
            app.removeRefFrameButton.Icon = 'remove.png';
            app.removeRefFrameButton.Text = 'Remove Ref. Frame';
            % Show the figure after all components are created
            app.lvd_EditGeometricRefFramesGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditGeometricRefFramesGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditGeometricRefFramesGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditGeometricRefFramesGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditGeometricRefFramesGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditGeometricRefFramesGUI)
        end
    end
end