classdef lvd_EditFixedInFrameVectorGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditFixedInFrameVectorGUI  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        uipanel1                       matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        uipanel5                       matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        vectorLineSpecCombo            matlab.ui.control.DropDown
        vectorLineColorCombo           matlab.ui.control.DropDown
        text30                         matlab.ui.control.Label
        uipanel3                       matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        GridLayout7                    matlab.ui.container.GridLayout
        GridLayout9                    matlab.ui.container.GridLayout
        text21                         matlab.ui.control.Label
        text19                         matlab.ui.control.Label
        text6                          matlab.ui.control.Label
        zCoordText                     matlab.ui.control.EditField
        yCoordText                     matlab.ui.control.EditField
        xCoordText                     matlab.ui.control.EditField
        GridLayout8                    matlab.ui.container.GridLayout
        refFrameTypeCombo              matlab.ui.control.DropDown
        setFrameOptionsButton          matlab.ui.control.Button
        GridLayout6                    matlab.ui.container.GridLayout
        text20                         matlab.ui.control.Label
        text18                         matlab.ui.control.Label
        text5                          matlab.ui.control.Label
        text22                         matlab.ui.control.Label
        uipanel2                       matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        vectorNameText                 matlab.ui.control.EditField
        vectorNameLabel                matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        cancelButton                   matlab.ui.control.Button
        saveAndCloseButton             matlab.ui.control.Button
        text2                          matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function frameUpdated(app, handles)
            lvdData = getappdata(handles.lvd_EditFixedInFrameVectorGUI, 'lvdData');

            frame = getappdata(handles.lvd_EditFixedInFrameVectorGUI,'frame'); %curElemSet
            bodyInfo = frame.getOriginBody();

            if(isempty(bodyInfo))
                bodyInfo = LvdData.getDefaultInitialBodyInfo(lvdData.celBodyData);
            end

            contents = cellstr(get(handles.refFrameTypeCombo,'String'));
            selFrameType = contents{get(handles.refFrameTypeCombo,'Value')};
            refFrameEnum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);

            switch refFrameEnum
                case ReferenceFrameEnum.BodyCenteredInertial
                    newFrame = bodyInfo.getBodyCenteredInertialFrame();

                case ReferenceFrameEnum.BodyFixedRotating
                    newFrame = bodyInfo.getBodyFixedFrame();

                case ReferenceFrameEnum.TwoBodyRotating
                    if(frame.typeEnum == ReferenceFrameEnum.TwoBodyRotating)
                        newFrame = frame;
                    else
                        if(not(isempty(bodyInfo.childrenBodyInfo)))
                            primaryBody = bodyInfo;
                            secondaryBody = bodyInfo.childrenBodyInfo(1);
                        else
                            primaryBody = bodyInfo.getParBodyInfo();
                            secondaryBody = bodyInfo;
                        end

                        originPt = TwoBodyRotatingFrameOriginEnum.Primary;

                        newFrame = TwoBodyRotatingFrame(primaryBody, secondaryBody, originPt, bodyInfo.celBodyData);
                    end

                case ReferenceFrameEnum.UserDefined
                    numFrames = lvdData.geometry.refFrames.getNumRefFrames();
                    if(numFrames >= 1)
                        geometricFrame = lvdData.geometry.refFrames.getRefFrameAtInd(1);
                        newFrame = UserDefinedGeometricFrame(geometricFrame, lvdData);
                    else
                        newFrame = bodyInfo.getBodyCenteredInertialFrame();
                        warndlg('There are no geometric frames available.  A body-centered inertial frame will be selected instead.');
                    end

                otherwise
                    error('Unknown reference frame type: %s', string(refFrameEnum));
            end

            if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
                refFrameEnum = newFrame.typeEnum;
                handles.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
            end

            setappdata(handles.lvd_EditFixedInFrameVectorGUI,'frame',newFrame);
            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', newFrame.getNameStr());
        end

        function populateGUI(app, handles, vector)
            set(handles.vectorNameText,'String',vector.getName());
            setappdata(handles.lvd_EditFixedInFrameVectorGUI,'frame',vector.frame);

            frame = vector.getFrame();
            handles.refFrameTypeCombo.String = ReferenceFrameEnum.getListBoxStr();
            handles.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(frame.typeEnum.name);
            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', frame.getNameStr());

            rVect = vector.getRVect();
            set(handles.xCoordText,'String',fullAccNum2Str(rVect(1)));
            set(handles.yCoordText,'String',fullAccNum2Str(rVect(2)));
            set(handles.zCoordText,'String',fullAccNum2Str(rVect(3)));

            handles.vectorLineColorCombo.String = ColorSpecEnum.getListboxStr();
            handles.vectorLineColorCombo.Value = ColorSpecEnum.getIndForName(vector.lineColor.name);

            handles.vectorLineSpecCombo.String = LineSpecEnum.getListboxStr();
            handles.vectorLineSpecCombo.Value = LineSpecEnum.getIndForName(vector.lineSpec.name);
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            xval = str2double(get(handles.xCoordText,'String'));
            enteredStr = get(handles.xCoordText,'String');
            numberName = 'X Coordinate';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(xval, numberName, lb, ub, isInt, errMsg, enteredStr);

            yval = str2double(get(handles.yCoordText,'String'));
            enteredStr = get(handles.yCoordText,'String');
            numberName = 'Y Coordinate';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(yval, numberName, lb, ub, isInt, errMsg, enteredStr);

            zval = str2double(get(handles.zCoordText,'String'));
            enteredStr = get(handles.zCoordText,'String');
            numberName = 'Z Coordinate';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(zval, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(normVector([xval, yval, zval]) == 0)
                errMsg{end+1} = 'The magnitude of the vector must be greater than 0.';
            end

            if(isempty(strtrim(handles.vectorNameText.String)))
                errMsg{end+1} = 'Vector name must contain more than white space and must not be empty.';
            end
        end


        function saveAndClose(app, handles)
            vector = getappdata(handles.lvd_EditFixedInFrameVectorGUI, 'vector');

            vector.setName(handles.vectorNameText.String);

            x = str2double(get(handles.xCoordText,'String'));
            y = str2double(get(handles.yCoordText,'String'));
            z = str2double(get(handles.zCoordText,'String'));
            vector.setRVect([x;y;z]);

            vector.frame = getappdata(handles.lvd_EditFixedInFrameVectorGUI,'frame');

            str = handles.vectorLineColorCombo.String{handles.vectorLineColorCombo.Value};
            vector.lineColor = ColorSpecEnum.getEnumForListboxStr(str);

            str = handles.vectorLineSpecCombo.String{handles.vectorLineSpecCombo.Value};
            vector.lineSpec = LineSpecEnum.getEnumForListboxStr(str);

            app.output.output = {true};
            close(handles.lvd_EditFixedInFrameVectorGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditFixedInFrameVectorGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditFixedInFrameVectorGUI (see VARARGIN)

            % Choose default command line output for lvd_EditFixedInFrameVectorGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            vector = varargin{1};
            setappdata(hObject, 'vector', vector);

            lvdData = varargin{2};
            setappdata(hObject, 'lvdData', lvdData);

            app.output = varargin{3};

            populateGUI(app, handles, vector);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditFixedInFrameVectorGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditFixedInFrameVectorGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditFixedInFrameVectorGUI);
        end

        % Window key press function: lvd_EditFixedInFrameVectorGUI
        function lvd_EditFixedInFrameVectorGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditFixedInFrameVectorGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditFixedInFrameVectorGUI);
            end
        end

        % Value changed function: refFrameTypeCombo
        function refFrameTypeCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to refFrameTypeCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns refFrameTypeCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from refFrameTypeCombo
            frameUpdated(app, handles);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                %                     uiresume(handles.lvd_EditFixedInFrameVectorGUI);
                app.saveAndClose(handles);
            else
                %                     msgbox(errMsg,'Invalid Vector Inputs','error');
                uialert(app.lvd_EditFixedInFrameVectorGUI, errMsg, 'Invalid Vector Inputs', "Icon","error");
            end
        end

        % Button pushed function: setFrameOptionsButton
        function setFrameOptionsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to setFrameOptionsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            frame = getappdata(handles.lvd_EditFixedInFrameVectorGUI,'frame');

            newFrame = frame.editFrameDialogUI(EditReferenceFrameContextEnum.ForGeometry);

            setappdata(handles.lvd_EditFixedInFrameVectorGUI,'frame',newFrame);

            frameUpdated(app, handles);
        end

        % Value changed function: xCoordText
        function xCoordText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to xCoordText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of xCoordText as text
            %        str2double(get(hObject,'String')) returns contents of xCoordText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: yCoordText
        function yCoordText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to yCoordText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of yCoordText as text
            %        str2double(get(hObject,'String')) returns contents of yCoordText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: zCoordText
        function zCoordText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to zCoordText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of zCoordText as text
            %        str2double(get(hObject,'String')) returns contents of zCoordText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditFixedInFrameVectorGUI = uifigure('Visible', 'off');
            app.lvd_EditFixedInFrameVectorGUI.Name = 'Edit Vector';
            app.lvd_EditFixedInFrameVectorGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditFixedInFrameVectorGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditFixedInFrameVectorGUI_WindowKeyPressFcn, true);
            app.lvd_EditFixedInFrameVectorGUI.Tag = 'lvd_EditFixedInFrameVectorGUI';
            app.GridLayout = uigridlayout(app.lvd_EditFixedInFrameVectorGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Vector';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Name';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4 = uigridlayout(app.uipanel2);
            app.vectorNameLabel = uilabel(app.GridLayout4);
            app.vectorNameLabel.Tag = 'vectorNameLabel';
            app.vectorNameLabel.Text = 'Vector Name';
            app.vectorNameText = uieditfield(app.GridLayout4, 'text');
            app.vectorNameText.Tag = 'vectorNameText';
            app.vectorNameText.Value = 'POINT NAME';
            app.uipanel3 = uipanel(app.GridLayout3);
            app.uipanel3.Title = 'Vector';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout5 = uigridlayout(app.uipanel3);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.text22 = uilabel(app.GridLayout6);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Frame Type';
            app.text5 = uilabel(app.GridLayout6);
            app.text5.Tag = 'text5';
            app.text5.Text = 'X Coordinate';
            app.text18 = uilabel(app.GridLayout6);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Y Coordinate';
            app.text20 = uilabel(app.GridLayout6);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Z Coordinate';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.setFrameOptionsButton = uibutton(app.GridLayout8, 'push');
            app.setFrameOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @setFrameOptionsButton_Callback, true);
            app.setFrameOptionsButton.Tag = 'setFrameOptionsButton';
            app.setFrameOptionsButton.Icon = 'reference_frame.png';
            app.setFrameOptionsButton.Text = 'Set Frame Options';
            app.refFrameTypeCombo = uidropdown(app.GridLayout8);
            app.refFrameTypeCombo.Items = {'Pop-up Menu'};
            app.refFrameTypeCombo.ValueChangedFcn = createCallbackFcn(app, @refFrameTypeCombo_Callback, true);
            app.refFrameTypeCombo.Tag = 'refFrameTypeCombo';
            app.refFrameTypeCombo.Tooltip = 'The reference frame with which the defined point is relative to.';
            app.refFrameTypeCombo.Value = 'Pop-up Menu';
            app.GridLayout9 = uigridlayout(app.GridLayout7);
            app.xCoordText = uieditfield(app.GridLayout9, 'text');
            app.xCoordText.ValueChangedFcn = createCallbackFcn(app, @xCoordText_Callback, true);
            app.xCoordText.Tag = 'xCoordText';
            app.xCoordText.Tooltip = 'The x coordinate of the location of this point with respect to the selected reference frame.';
            app.xCoordText.Value = '1';
            app.yCoordText = uieditfield(app.GridLayout9, 'text');
            app.yCoordText.ValueChangedFcn = createCallbackFcn(app, @yCoordText_Callback, true);
            app.yCoordText.Tag = 'yCoordText';
            app.yCoordText.Tooltip = 'The y coordinate of the location of this point with respect to the selected reference frame.';
            app.yCoordText.Value = '1';
            app.zCoordText = uieditfield(app.GridLayout9, 'text');
            app.zCoordText.ValueChangedFcn = createCallbackFcn(app, @zCoordText_Callback, true);
            app.zCoordText.Tag = 'zCoordText';
            app.zCoordText.Tooltip = 'The z coordinate of the location of this point with respect to the selected reference frame.';
            app.zCoordText.Value = '1';
            app.text6 = uilabel(app.GridLayout9);
            app.text6.Tag = 'text6';
            app.text6.Text = 'km';
            app.text19 = uilabel(app.GridLayout9);
            app.text19.Tag = 'text19';
            app.text19.Text = 'km';
            app.text21 = uilabel(app.GridLayout9);
            app.text21.Tag = 'text21';
            app.text21.Text = 'km';
            app.uipanel5 = uipanel(app.GridLayout3);
            app.uipanel5.Title = 'Display';
            app.uipanel5.Tag = 'uipanel5';
            app.GridLayout10 = uigridlayout(app.uipanel5);
            app.text30 = uilabel(app.GridLayout10);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Vector Line';
            app.vectorLineColorCombo = uidropdown(app.GridLayout10);
            app.vectorLineColorCombo.Items = {'Pop-up Menu'};
            app.vectorLineColorCombo.Tag = 'vectorLineColorCombo';
            app.vectorLineColorCombo.Tooltip = 'The color of the vector''s line on the display.';
            app.vectorLineColorCombo.Value = 'Pop-up Menu';
            app.vectorLineSpecCombo = uidropdown(app.GridLayout10);
            app.vectorLineSpecCombo.Items = {'Pop-up Menu'};
            app.vectorLineSpecCombo.Tag = 'vectorLineSpecCombo';
            app.vectorLineSpecCombo.Tooltip = 'The style of the vector''s line on the display.';
            app.vectorLineSpecCombo.Value = 'Pop-up Menu';
            app.Image = uiimage(app.GridLayout10);
            app.Image.ImageSource = 'color_wheel.png';
            app.Image2 = uiimage(app.GridLayout10);
            app.Image2.ImageSource = 'line.png';
            % Show the figure after all components are created
            app.lvd_EditFixedInFrameVectorGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditFixedInFrameVectorGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditFixedInFrameVectorGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditFixedInFrameVectorGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditFixedInFrameVectorGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditFixedInFrameVectorGUI)
        end
    end
end