classdef lvd_EditGeometricVectorsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditGeometricVectorsGUI  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout2                  matlab.ui.container.GridLayout
        GridLayout3                  matlab.ui.container.GridLayout
        removeVectorButton           matlab.ui.control.Button
        addVectorButton              matlab.ui.control.Button
        vectorsListBox               matlab.ui.control.ListBox
        vectorsSearchText            matlab.ui.control.EditField
        closeButton                  matlab.ui.control.Button
        text2                        matlab.ui.control.Label
    end

    methods (Access = private)
        function populateGUI(app, handles, lvdData)
            [~, ~] = app.setListboxStr();
        end

        function [listBoxStr, vectors] = setListboxStr(app, query)
            if(nargin < 2)
                query = app.vectorsSearchText.Value;
            end
            
            hFig = app.lvd_EditGeometricVectorsGUI;
            currentValue = app.vectorsListBox.Value;
            hadSelection = isa(currentValue, 'AbstractGeometricVector');
            if(hadSelection)
                setappdata(hFig, 'h8Selected', currentValue);
            end
            lvdData = getappdata(app.lvd_EditGeometricVectorsGUI,'lvdData');
            [listBoxStr, vectors] = lvdData.geometry.vectors.getListboxStr();
            keep = lvd_filterListboxItems(query, listBoxStr);
            listBoxStr = listBoxStr(keep);
            vectors = vectors(keep);
            storedValue = [];
            if(isappdata(hFig, 'h8Selected'))
                storedValue = getappdata(hFig, 'h8Selected');
            end
            selectedValue = lvd_selectVisibleListValue({currentValue, storedValue}, vectors);
            app.vectorsListBox.ItemsData = vectors;
            app.vectorsListBox.Items = listBoxStr;
            if(not(isempty(selectedValue)))
                app.vectorsListBox.Value = selectedValue;
                setappdata(hFig, 'h8Selected', selectedValue);
            elseif(hadSelection)
                %A visible selection that was filtered out stays cleared.
            elseif(not(isempty(vectors)))
                app.vectorsListBox.Value = vectors(1);
                setappdata(hFig, 'h8Selected', vectors(1));
            end
            
            num = numel(vectors);
            if(num <= 0)
                app.removeVectorButton.Enable = 'off';
            else
                app.removeVectorButton.Enable = 'on';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditGeometricVectorsGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditGeometricVectorsGUI (see VARARGIN)

            % Choose default command line output for lvd_EditGeometricVectorsGUI
            handles.output = hObject;
            
            centerUIFigure(app.lvd_EditGeometricVectorsGUI);
            applySelectedThemeToApp(app);

            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            populateGUI(app, handles, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditGeometricVectorsGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditGeometricVectorsGUI);
        end

        % Button pushed function: addVectorButton
        function addVectorButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addVectorButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditGeometricVectorsGUI,'lvdData');

            listboxStr = GeometricVectorEnum.getListBoxStr();
            out = AppDesignerGUIOutput();
            listdlgARH_App('ListString', listboxStr, ...
                'SelectionMode', 'single', ...
                'ListSize', [300 300], ...
                'InitialValue',1, ...
                'Name', 'Select New Vector Type', ...
                'PromptString', 'Select the type of the vector to be created:', ...
                'out',out);
            Selection = out.output{1};
            ok = out.output{2};

            if(ok)
                enum = GeometricVectorEnum.getEnumForListboxStr(listboxStr{Selection});

                switch enum
                    case GeometricVectorEnum.FixedInFrame
                        frame = lvdData.getDefaultInitialBodyInfo(lvdData.celBodyData).getBodyCenteredInertialFrame();
                        newVector = FixedVectorInFrame([1;0;0], frame, 'New Vector', lvdData);

                    case GeometricVectorEnum.TwoPoint
                        numPoints = lvdData.geometry.points.getNumPoints();
                        if(numPoints >= 2)
                            plane = lvdData.geometry.points.getPointAtInd(1);
                            point = lvdData.geometry.points.getPointAtInd(2);
                            newVector = TwoPointVector(plane, point, 'New Vector', lvdData);
                        else
                            uialert(app.lvd_EditGeometricVectorsGUI, 'Cannot create Two Point Vector: There must be at least two unique geometric points available.', 'Cannot Create Vector',"Modal",true);
                            return;
                        end

                    case GeometricVectorEnum.Scaled
                        numVectors = lvdData.geometry.vectors.getNumVectors();
                        if(numVectors >= 1)
                            vector = lvdData.geometry.vectors.getVectorAtInd(1);
                            newVector = ScaledVector(vector, 1.0, 'New Vector', lvdData);
                        else
                            uialert(app.lvd_EditGeometricVectorsGUI, 'Cannot create Scaled Vector: There must be at least one geometric vector available.', 'Cannot Create Vector',"Modal",true);
                            return;
                        end

                    case GeometricVectorEnum.CrossProd
                        numVectors = lvdData.geometry.vectors.getNumVectors();
                        if(numVectors >= 2)
                            vector1 = lvdData.geometry.vectors.getVectorAtInd(1);
                            vector2 = lvdData.geometry.vectors.getVectorAtInd(2);
                            newVector = CrossProductVector(vector1, vector2, 'New Vector', lvdData);
                        else
                            uialert(app.lvd_EditGeometricVectorsGUI, 'Cannot create Cross Product Vector: There must be at least two unique geometric vectors available.', 'Cannot Create Vector',"Modal",true);
                            return;
                        end

                    case GeometricVectorEnum.VehicleState
                        newVector = VehicleStateVector(VehicleStateVectorTypeEnum.Velocity, 1, 'New Vector', lvdData);

                    case GeometricVectorEnum.Projected
                        numVectors = lvdData.geometry.vectors.getNumVectors();
                        if(numVectors >= 2)
                            vector1 = lvdData.geometry.vectors.getVectorAtInd(1);
                            vector2 = lvdData.geometry.vectors.getVectorAtInd(2);
                            newVector = ProjectedVector(vector1, vector2, 'New Vector', lvdData);
                        else
                            uialert(app.lvd_EditGeometricVectorsGUI, 'Cannot create Projected Vector: There must be at least two unique geometric vectors available.', 'Cannot Create Vector',"Modal",true);
                            return;
                        end

                    case GeometricVectorEnum.PlaneToPoint
                        numPoints = lvdData.geometry.points.getNumPoints();
                        numPlanes = lvdData.geometry.planes.getNumPlanes();
                        if(numPoints >= 1 && numPlanes >= 1)
                            plane = lvdData.geometry.planes.getPlaneAtInd(1);
                            point = lvdData.geometry.points.getPointAtInd(1);
                            newVector = PlaneToPointVector(point, plane, 'New Vector', lvdData);
                        else
                            uialert(app.lvd_EditGeometricVectorsGUI, 'Cannot create Plane to Point Vector: There must be at least one unique geometric point and one unique geometric plane available.', 'Cannot Create Vector',"Modal",true);
                            return;
                        end

                    case GeometricVectorEnum.PointVelocityVector
                        numPoints = lvdData.geometry.points.getNumPoints();
                        if(numPoints >= 1)
                            point = lvdData.geometry.points.getPointAtInd(1);
                            newVector = PointVelocityVector(point, 'New Vector', lvdData);
                        else
                            uialert(app.lvd_EditGeometricVectorsGUI, 'Cannot create Point Velocity Vector: There must be at least one geometric point available.', 'Cannot Create Vector',"Modal",true);
                            return;
                        end

                    case GeometricVectorEnum.VectorDifference
                        numVectors = lvdData.geometry.vectors.getNumVectors();
                        if(numVectors >= 2)
                            vector1 = lvdData.geometry.vectors.getVectorAtInd(1);
                            vector2 = lvdData.geometry.vectors.getVectorAtInd(2);
                            newVector = VectorDifferenceVector(vector1, vector2, 'New Vector', lvdData);
                        else
                            uialert(app.lvd_EditGeometricVectorsGUI, 'Cannot create Vector Difference: There must be at least two unique geometric vectors available.', 'Cannot Create Vector',"Modal",true);
                            return;
                        end
                    case GeometricVectorEnum.UnitVector
                        numVectors = lvdData.geometry.vectors.getNumVectors();
                        if(numVectors >= 1)
                            vector = lvdData.geometry.vectors.getVectorAtInd(1);
                            newVector = UnitVector(vector, 'New Vector', lvdData);
                        else
                            uialert(app.lvd_EditGeometricVectorsGUI, 'Cannot create Unit Vector: There must be at least one geometric vector available.', 'Cannot Create Vector',"Modal",true);
                            return;
                        end

                    case GeometricVectorEnum.VectorSum
                        numVectors = lvdData.geometry.vectors.getNumVectors();
                        if(numVectors >= 2)
                            vector1 = lvdData.geometry.vectors.getVectorAtInd(1);
                            vector2 = lvdData.geometry.vectors.getVectorAtInd(2);
                            newVector = VectorSumVector(vector1, vector2, 'New Vector', lvdData);
                        else
                            uialert(app.lvd_EditGeometricVectorsGUI, 'Cannot create Vector Sum: There must be at least two unique geometric vectors available.', 'Cannot Create Vector',"Modal",true);
                            return;
                        end

                    otherwise
                        error('Unknown Vector Type Type: %s', string(enum))
                end

                useTF = newVector.openEditDialog();

                if(useTF)
                    lvdData.geometry.vectors.addVector(newVector);
                app.vectorsSearchText.Value = '';
                [~, ~] = app.setListboxStr('');
                app.vectorsListBox.Value = newVector;
                end
            end
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to closeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditGeometricVectorsGUI);
        end

        % Window key press function: lvd_EditGeometricVectorsGUI
        function lvd_EditGeometricVectorsGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
 
            % hObject    handle to figure
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            switch(eventdata.Key)
                case 'return'
                    if(app.lvd_EditGeometricVectorsGUI.CurrentObject == app.vectorsSearchText)
                        return;
                    end
                    close(handles.lvd_EditGeometricVectorsGUI);
                case 'enter'
                    if(app.lvd_EditGeometricVectorsGUI.CurrentObject == app.vectorsSearchText)
                        return;
                    end
                    close(handles.lvd_EditGeometricVectorsGUI);
                case 'escape'
                    close(handles.lvd_EditGeometricVectorsGUI);
            end
        end

        % Button pushed function: removeVectorButton
        function removeVectorButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeVectorButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditGeometricVectorsGUI,'lvdData');

            oldInd = app.vectorsListBox.ValueIndex;
            selVector = app.vectorsListBox.Value;

            tf = selVector.isInUse(lvdData);

            if(tf == false)
                lvdData.geometry.vectors.removeVector(selVector);

                lvdData.viewSettings.removeGeoVectorFromViewProfiles(selVector);

                [~, visibleObjs] = app.setListboxStr(app.vectorsSearchText.Value);
                if(not(isempty(visibleObjs)))
                app.vectorsListBox.Value = visibleObjs(min(oldInd, numel(visibleObjs)));
                end
            else
                msg = sprintf('Could not delete the geometric vector "%s" because it is in use as part of a vector, coordinate system, or reference frame.  Remove the vector dependencies before attempting to delete the vector.', selVector.getName());
%                 warndlg(msg,'Cannot Delete Vector','modal');
                uialert(app.lvd_EditGeometricVectorsGUI, msg, 'Cannot Delete Vector',"Modal",true);
            end
        end

        % Value changed function: vectorsListBox
        function vectorsListBox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to vectorsListBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns vectorsListBox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from vectorsListBox
%             lvdData = getappdata(handles.lvd_EditGeometricVectorsGUI,'lvdData');
%             if(strcmpi(get(handles.lvd_EditGeometricVectorsGUI,'SelectionType'),'open') && ...
%                     lvdData.geometry.vectors.getNumVectors() > 0)
%                 selVector = app.vectorsListBox.Value;
% 
%                 selVector.openEditDialog();
% 
%                 [~, ~] = app.setListboxStr();
%             end
        end

        % Window button down function: lvd_EditGeometricVectorsGUI
        function lvd_EditGeometricVectorsGUIWindowButtonDown(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            selType = app.lvd_EditGeometricVectorsGUI.SelectionType;
            curObj = app.lvd_EditGeometricVectorsGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.vectorsListBox && numel(app.vectorsListBox.ItemsData) > 0)
                    selVector = app.vectorsListBox.Value;
                    selVector.openEditDialog();
    
                    [~, ~] = app.setListboxStr();
                end
            end
        end

 
        % Value changing function: vectorsSearchText
        function vectorsSearchTextValueChanging(app, event)
            [~, ~] = app.setListboxStr(event.Value);
        end
        % Value changed function: vectorsSearchText
        function vectorsSearchTextValueChanged(app, event)
            [~, ~] = app.setListboxStr(event.Value);
        end
    end
    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditGeometricVectorsGUI = uifigure('Visible', 'off');
            app.lvd_EditGeometricVectorsGUI.Name = 'Edit Vectors';
            app.lvd_EditGeometricVectorsGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditGeometricVectorsGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_EditGeometricVectorsGUIWindowButtonDown, true);
            app.lvd_EditGeometricVectorsGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditGeometricVectorsGUI_WindowKeyPressFcn, true);
            app.lvd_EditGeometricVectorsGUI.Tag = 'lvd_EditGeometricVectorsGUI';
            app.GridLayout = uigridlayout(app.lvd_EditGeometricVectorsGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Vectors';
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Icon = 'close_window(1).png';
            app.closeButton.Text = 'Close';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.vectorsSearchText = uieditfield(app.GridLayout2, 'text');
            app.vectorsSearchText.ValueChangingFcn = createCallbackFcn(app, @vectorsSearchTextValueChanging, true);
            app.vectorsSearchText.ValueChangedFcn = createCallbackFcn(app, @vectorsSearchTextValueChanged, true);
            app.vectorsSearchText.Tag = 'vectorsSearchText';
            app.vectorsSearchText.Tooltip = 'Type to filter the vectors below.';
            app.vectorsSearchText.Placeholder = 'Search vectors...';
            app.vectorsListBox = uilistbox(app.GridLayout2);
            app.vectorsListBox.Items = {'Listbox'};
            app.vectorsListBox.ValueChangedFcn = createCallbackFcn(app, @vectorsListBox_Callback, true);
            app.vectorsListBox.Tag = 'vectorsListBox';
            app.vectorsListBox.Tooltip = 'Double click an entry to edit it.';
            app.vectorsListBox.Value = 'Listbox';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.addVectorButton = uibutton(app.GridLayout3, 'push');
            app.addVectorButton.ButtonPushedFcn = createCallbackFcn(app, @addVectorButton_Callback, true);
            app.addVectorButton.Tag = 'addVectorButton';
            app.addVectorButton.Icon = 'add.png';
            app.addVectorButton.Text = 'Add Vector';
            app.removeVectorButton = uibutton(app.GridLayout3, 'push');
            app.removeVectorButton.ButtonPushedFcn = createCallbackFcn(app, @removeVectorButton_Callback, true);
            app.removeVectorButton.Tag = 'removeVectorButton';
            app.removeVectorButton.Icon = 'cancel.png';
            app.removeVectorButton.Text = 'Remove Vector';
            % Show the figure after all components are created
            app.lvd_EditGeometricVectorsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditGeometricVectorsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditGeometricVectorsGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditGeometricVectorsGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditGeometricVectorsGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditGeometricVectorsGUI)
        end
    end
end