classdef lvd_EditTwoPointVectorGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditTwoPointVectorGUI  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        uipanel1                   matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        uipanel5                   matlab.ui.container.Panel
        GridLayout6                matlab.ui.container.GridLayout
        Image4                     matlab.ui.control.Image
        Image3                     matlab.ui.control.Image
        vectorLineSpecCombo        matlab.ui.control.DropDown
        vectorLineColorCombo       matlab.ui.control.DropDown
        text30                     matlab.ui.control.Label
        uipanel3                   matlab.ui.container.Panel
        GridLayout5                matlab.ui.container.GridLayout
        Image2                     matlab.ui.control.Image
        Image                      matlab.ui.control.Image
        vectorTerminusCombo        matlab.ui.control.DropDown
        vectorOriginCombo          matlab.ui.control.DropDown
        text31                     matlab.ui.control.Label
        text5                      matlab.ui.control.Label
        uipanel2                   matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        vectorNameText             matlab.ui.control.EditField
        stageName                  matlab.ui.control.Label
        GridLayout2                matlab.ui.container.GridLayout
        cancelButton               matlab.ui.control.Button
        saveAndCloseButton         matlab.ui.control.Button
        text2                      matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, lvdData, vector)
            set(handles.vectorNameText,'String',vector.getName());

            pointsListBoxStr = lvdData.geometry.points.getListboxStr();

            handles.vectorOriginCombo.String = pointsListBoxStr;
            handles.vectorOriginCombo.Value = lvdData.geometry.points.getIndsForPoints(vector.point1);

            handles.vectorTerminusCombo.String = pointsListBoxStr;
            handles.vectorTerminusCombo.Value = lvdData.geometry.points.getIndsForPoints(vector.point2);

            handles.vectorLineColorCombo.String = ColorSpecEnum.getListboxStr();
            handles.vectorLineColorCombo.Value = ColorSpecEnum.getIndForName(vector.lineColor.name);

            handles.vectorLineSpecCombo.String = LineSpecEnum.getListboxStr();
            handles.vectorLineSpecCombo.Value = LineSpecEnum.getIndForName(vector.lineSpec.name);
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            if(handles.vectorOriginCombo.Value == handles.vectorTerminusCombo.Value)
                errMsg{end+1} = 'The origin and terminus points must be different.';
            end

            if(isempty(strtrim(handles.vectorNameText.String)))
                errMsg{end+1} = 'Vector name must contain more than white space and must not be empty.';
            end
        end


        function saveAndClose(app, handles)
            lvdData = getappdata(handles.lvd_EditTwoPointVectorGUI, 'lvdData');
            vector = getappdata(handles.lvd_EditTwoPointVectorGUI, 'vector');

            vector.setName(handles.vectorNameText.String);

            vector.point1 = lvdData.geometry.points.getPointsForInds(handles.vectorOriginCombo.Value);
            vector.point2 = lvdData.geometry.points.getPointsForInds(handles.vectorTerminusCombo.Value);

            str = handles.vectorLineColorCombo.String{handles.vectorLineColorCombo.Value};
            vector.lineColor = ColorSpecEnum.getEnumForListboxStr(str);

            str = handles.vectorLineSpecCombo.String{handles.vectorLineSpecCombo.Value};
            vector.lineSpec = LineSpecEnum.getEnumForListboxStr(str);

            app.output.output = {true};
            close(handles.lvd_EditTwoPointVectorGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditTwoPointVectorGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditTwoPointVectorGUI (see VARARGIN)

            % Choose default command line output for lvd_EditTwoPointVectorGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            vector = varargin{1};
            setappdata(hObject, 'vector', vector);

            lvdData = varargin{2};
            setappdata(hObject, 'lvdData', lvdData);

            app.output = varargin{3};

            populateGUI(app, handles, lvdData, vector);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditTwoPointVectorGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditTwoPointVectorGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditTwoPointVectorGUI);
        end

        % Window key press function: lvd_EditTwoPointVectorGUI
        function lvd_EditTwoPointVectorGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditTwoPointVectorGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditTwoPointVectorGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
%                 uiresume(handles.lvd_EditTwoPointVectorGUI);
                app.saveAndClose(handles);
            else
%                 msgbox(errMsg,'Invalid Vector Inputs','error');
                uialert(app.lvd_EditTwoPointVectorGUI, errMsg, 'Invalid Vector Inputs', "Icon","error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditTwoPointVectorGUI = uifigure('Visible', 'off');
            app.lvd_EditTwoPointVectorGUI.Name = 'Edit Vector';
            app.lvd_EditTwoPointVectorGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditTwoPointVectorGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditTwoPointVectorGUI_WindowKeyPressFcn, true);
            app.lvd_EditTwoPointVectorGUI.Tag = 'lvd_EditTwoPointVectorGUI';
            app.GridLayout = uigridlayout(app.lvd_EditTwoPointVectorGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Vector';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Name';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4 = uigridlayout(app.uipanel2);
            app.stageName = uilabel(app.GridLayout4);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Vector Name';
            app.vectorNameText = uieditfield(app.GridLayout4, 'text');
            app.vectorNameText.Tag = 'vectorNameText';
            app.vectorNameText.Value = 'POINT NAME';
            app.uipanel3 = uipanel(app.GridLayout3);
            app.uipanel3.Title = 'Vector';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout5 = uigridlayout(app.uipanel3);
            app.text5 = uilabel(app.GridLayout5);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Vector Origin';
            app.text31 = uilabel(app.GridLayout5);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Vector Terminus';
            app.vectorOriginCombo = uidropdown(app.GridLayout5);
            app.vectorOriginCombo.Items = {'Pop-up Menu'};
            app.vectorOriginCombo.Tag = 'vectorOriginCombo';
            app.vectorOriginCombo.Tooltip = 'This point is the origin of the vector.';
            app.vectorOriginCombo.Value = 'Pop-up Menu';
            app.vectorTerminusCombo = uidropdown(app.GridLayout5);
            app.vectorTerminusCombo.Items = {'Pop-up Menu'};
            app.vectorTerminusCombo.Tag = 'vectorTerminusCombo';
            app.vectorTerminusCombo.Tooltip = 'This point is the end point of the vector.';
            app.vectorTerminusCombo.Value = 'Pop-up Menu';
            app.Image = uiimage(app.GridLayout5);
            app.Image.ImageSource = 'circle(1).png';
            app.Image2 = uiimage(app.GridLayout5);
            app.Image2.ImageSource = 'circle_2.png';
            app.uipanel5 = uipanel(app.GridLayout3);
            app.uipanel5.Title = 'Display';
            app.uipanel5.Tag = 'uipanel5';
            app.GridLayout6 = uigridlayout(app.uipanel5);
            app.text30 = uilabel(app.GridLayout6);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Vector Line';
            app.vectorLineColorCombo = uidropdown(app.GridLayout6);
            app.vectorLineColorCombo.Items = {'Pop-up Menu'};
            app.vectorLineColorCombo.Tag = 'vectorLineColorCombo';
            app.vectorLineColorCombo.Tooltip = 'The color of the vector''s line on the display.';
            app.vectorLineColorCombo.Value = 'Pop-up Menu';
            app.vectorLineSpecCombo = uidropdown(app.GridLayout6);
            app.vectorLineSpecCombo.Items = {'Pop-up Menu'};
            app.vectorLineSpecCombo.Tag = 'vectorLineSpecCombo';
            app.vectorLineSpecCombo.Tooltip = 'The style of the vector''s line on the display.';
            app.vectorLineSpecCombo.Value = 'Pop-up Menu';
            app.Image3 = uiimage(app.GridLayout6);
            app.Image3.ImageSource = 'color_wheel.png';
            app.Image4 = uiimage(app.GridLayout6);
            app.Image4.ImageSource = 'line.png';
            % Show the figure after all components are created
            app.lvd_EditTwoPointVectorGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditTwoPointVectorGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditTwoPointVectorGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditTwoPointVectorGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditTwoPointVectorGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditTwoPointVectorGUI)
        end
    end
end