classdef lvd_EditVehicleStateVectorGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditVehicleStateVectorGUI  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        uipanel1                       matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        uipanel5                       matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        Image3                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        vectorLineSpecCombo            matlab.ui.control.DropDown
        vectorLineColorCombo           matlab.ui.control.DropDown
        text30                         matlab.ui.control.Label
        uipanel3                       matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
        scaleFactorText                matlab.ui.control.EditField
        typeCombo                      matlab.ui.control.DropDown
        text32                         matlab.ui.control.Label
        text5                          matlab.ui.control.Label
        uipanel2                       matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        vectorNameText                 matlab.ui.control.EditField
        stageName                      matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        cancelButton                   matlab.ui.control.Button
        saveAndCloseButton             matlab.ui.control.Button
        text2                          matlab.ui.control.Label
    end


    properties (Access = private)
        output  AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, vector)
            set(handles.vectorNameText,'String',vector.getName());

            handles.typeCombo.String = VehicleStateVectorTypeEnum.getListBoxStr();
            handles.typeCombo.Value = VehicleStateVectorTypeEnum.getIndForName(vector.type.name);

            handles.scaleFactorText.String = fullAccNum2Str(vector.scaleFactor);

            handles.vectorLineColorCombo.String = ColorSpecEnum.getListboxStr();
            handles.vectorLineColorCombo.Value = ColorSpecEnum.getIndForName(vector.lineColor.name);

            handles.vectorLineSpecCombo.String = LineSpecEnum.getListboxStr();
            handles.vectorLineSpecCombo.Value = LineSpecEnum.getIndForName(vector.lineSpec.name);
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            scaleFactor = str2double(get(handles.scaleFactorText,'String'));
            enteredStr = get(handles.scaleFactorText,'String');
            numberName = 'Scale Factor';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(scaleFactor, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                if(scaleFactor == 0)
                    errMsg{end+1} = 'Absolute value of scale factor must be greater than zero.';
                end
            end

            if(isempty(strtrim(handles.vectorNameText.String)))
                errMsg{end+1} = 'Vector name must contain more than white space and must not be empty.';
            end
        end

        function saveAndClose(app, handles)
            vector = getappdata(handles.lvd_EditVehicleStateVectorGUI, 'vector');

            vector.setName(handles.vectorNameText.String);

            str = handles.typeCombo.String{handles.typeCombo.Value};
            vector.type = VehicleStateVectorTypeEnum.getEnumForListboxStr(str);

            vector.scaleFactor = str2double(handles.scaleFactorText.String);

            str = handles.vectorLineColorCombo.String{handles.vectorLineColorCombo.Value};
            vector.lineColor = ColorSpecEnum.getEnumForListboxStr(str);

            str = handles.vectorLineSpecCombo.String{handles.vectorLineSpecCombo.Value};
            vector.lineSpec = LineSpecEnum.getEnumForListboxStr(str);

            app.output.output = {true};
            close(handles.lvd_EditVehicleStateVectorGUI);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditVehicleStateVectorGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditVehicleStateVectorGUI (see VARARGIN)

            % Choose default command line output for lvd_EditVehicleStateVectorGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            vector = varargin{1};
            setappdata(hObject, 'vector', vector);

            lvdData = varargin{2};
            setappdata(hObject, 'lvdData', lvdData);

            app.output = varargin{3};

            populateGUI(app, handles, vector);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditVehicleStateVectorGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditVehicleStateVectorGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditVehicleStateVectorGUI);
        end

        % Window key press function: lvd_EditVehicleStateVectorGUI
        function lvd_EditVehicleStateVectorGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditVehicleStateVectorGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditVehicleStateVectorGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                %                     uiresume(handles.lvd_EditVehicleStateVectorGUI);
                app.saveAndClose(handles);
            else
                %                     msgbox(errMsg,'Invalid Vector Inputs','error');
                uialert(app.lvd_EditVehicleStateVectorGUI, errMsg, 'Invalid Vector Inputs', "Icon","error");
            end
        end

        % Value changed function: scaleFactorText
        function scaleFactorText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to scaleFactorText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of scaleFactorText as text
            %        str2double(get(hObject,'String')) returns contents of scaleFactorText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditVehicleStateVectorGUI = uifigure('Visible', 'off');
            app.lvd_EditVehicleStateVectorGUI.Name = 'Edit Vector';
            app.lvd_EditVehicleStateVectorGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditVehicleStateVectorGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditVehicleStateVectorGUI_WindowKeyPressFcn, true);
            app.lvd_EditVehicleStateVectorGUI.Tag = 'lvd_EditVehicleStateVectorGUI';
            app.GridLayout = uigridlayout(app.lvd_EditVehicleStateVectorGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Vector';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Name';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4 = uigridlayout(app.uipanel2);
            app.stageName = uilabel(app.GridLayout4);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Vector Name';
            app.vectorNameText = uieditfield(app.GridLayout4, 'text');
            app.vectorNameText.Tag = 'vectorNameText';
            app.vectorNameText.Value = 'POINT NAME';
            app.uipanel3 = uipanel(app.GridLayout3);
            app.uipanel3.Title = 'Vector';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout5 = uigridlayout(app.uipanel3);
            app.text5 = uilabel(app.GridLayout5);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Vector';
            app.text32 = uilabel(app.GridLayout5);
            app.text32.Tag = 'text32';
            app.text32.Text = 'Scale Factor';
            app.typeCombo = uidropdown(app.GridLayout5);
            app.typeCombo.Items = {'Pop-up Menu'};
            app.typeCombo.Tag = 'typeCombo';
            app.typeCombo.Tooltip = 'The type of vector, based on the current vehicle state.';
            app.typeCombo.Value = 'Pop-up Menu';
            app.scaleFactorText = uieditfield(app.GridLayout5, 'text');
            app.scaleFactorText.ValueChangedFcn = createCallbackFcn(app, @scaleFactorText_Callback, true);
            app.scaleFactorText.Tag = 'scaleFactorText';
            app.scaleFactorText.Tooltip = 'The factor by which the selected vector will be scaled (multipled) by.';
            app.scaleFactorText.Value = 'Pop-up Menu';
            app.Image = uiimage(app.GridLayout5);
            app.Image.ImageSource = 'green-arrow.png';
            app.uipanel5 = uipanel(app.GridLayout3);
            app.uipanel5.Title = 'Display';
            app.uipanel5.Tag = 'uipanel5';
            app.GridLayout6 = uigridlayout(app.uipanel5);
            app.text30 = uilabel(app.GridLayout6);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Vector Line';
            app.vectorLineColorCombo = uidropdown(app.GridLayout6);
            app.vectorLineColorCombo.Items = {'Pop-up Menu'};
            app.vectorLineColorCombo.Tag = 'vectorLineColorCombo';
            app.vectorLineColorCombo.Tooltip = 'The color of the vector''s line on the display.';
            app.vectorLineColorCombo.Value = 'Pop-up Menu';
            app.vectorLineSpecCombo = uidropdown(app.GridLayout6);
            app.vectorLineSpecCombo.Items = {'Pop-up Menu'};
            app.vectorLineSpecCombo.Tag = 'vectorLineSpecCombo';
            app.vectorLineSpecCombo.Tooltip = 'The style of the vector''s line on the display.';
            app.vectorLineSpecCombo.Value = 'Pop-up Menu';
            app.Image2 = uiimage(app.GridLayout6);
            app.Image2.ImageSource = 'color_wheel.png';
            app.Image3 = uiimage(app.GridLayout6);
            app.Image3.ImageSource = 'line.png';
            % Show the figure after all components are created
            app.lvd_EditVehicleStateVectorGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditVehicleStateVectorGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditVehicleStateVectorGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditVehicleStateVectorGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditVehicleStateVectorGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditVehicleStateVectorGUI)
        end
    end
end