classdef lvd_EditGroundObjectsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditGroundObjectsGUI    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        Panel                       matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        EditGroundObjectPanel       matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        uipanel3                    matlab.ui.container.Panel
        GridLayout11                matlab.ui.container.GridLayout
        GridLayout13                matlab.ui.container.GridLayout
        GridLayout14                matlab.ui.container.GridLayout
        moveWaypointUpButton        matlab.ui.control.Button
        moveWaypointDownButton      matlab.ui.control.Button
        removeWaypointButton        matlab.ui.control.Button
        addWaypointButton           matlab.ui.control.Button
        waypointsListbox            matlab.ui.control.ListBox
        uipanel5                    matlab.ui.container.Panel
        GridLayout12                matlab.ui.container.GridLayout
        distToNextWaypointLabel     matlab.ui.control.Label
        timeToNextWaypointText      matlab.ui.control.EditField
        waypointAltText             matlab.ui.control.EditField
        waypointLongText            matlab.ui.control.EditField
        waypointLatText             matlab.ui.control.EditField
        text19                      matlab.ui.control.Label
        text16                      matlab.ui.control.Label
        text14                      matlab.ui.control.Label
        text12                      matlab.ui.control.Label
        text10                      matlab.ui.control.Label
        text10000                   matlab.ui.control.Label
        text17                      matlab.ui.control.Label
        text15                      matlab.ui.control.Label
        text13                      matlab.ui.control.Label
        text11                      matlab.ui.control.Label
        uipanel4                    matlab.ui.container.Panel
        GridLayout9                 matlab.ui.container.GridLayout
        GridLayout10                matlab.ui.container.GridLayout
        initialTimeText             matlab.ui.control.EditField
        text8                       matlab.ui.control.Label
        loopGroundObjTrackCheckbox  matlab.ui.control.CheckBox
        extrapolateTimesCheckbox    matlab.ui.control.CheckBox
        text7                       matlab.ui.control.Label
        uipanel6                    matlab.ui.container.Panel
        GridLayout8                 matlab.ui.container.GridLayout
        Image2                      matlab.ui.control.Image
        Image                       matlab.ui.control.Image
        groundObjColorCombo         matlab.ui.control.DropDown
        text6                       matlab.ui.control.Label
        text5                       matlab.ui.control.Label
        groundObjLineSpecCombo      matlab.ui.control.DropDown
        groundObjMarkerShapeCombo   matlab.ui.control.DropDown
        groundObjLineColorCombo     matlab.ui.control.DropDown
        GridLayout7                 matlab.ui.container.GridLayout
        celestialBodyCombo          matlab.ui.control.DropDown
        text18                      matlab.ui.control.Label
        GridLayout6                 matlab.ui.container.GridLayout
        groundObjDescText           matlab.ui.control.TextArea
        text4                       matlab.ui.control.Label
        GridLayout5                 matlab.ui.container.GridLayout
        groundObjectNameText        matlab.ui.control.EditField
        text3                       matlab.ui.control.Label
        GridLayout3                 matlab.ui.container.GridLayout
        groundObjListbox            matlab.ui.control.ListBox
        removeGroundObjButton       matlab.ui.control.Button
        addGroundObjButton          matlab.ui.control.Button
        saveAndCloseButton          matlab.ui.control.Button
        text2                       matlab.ui.control.Label
        initialTimeContextMenu      matlab.ui.container.ContextMenu
        enterUTAsDateTimeMenu       matlab.ui.container.Menu
        getUTFromKSPMenu            matlab.ui.container.Menu
    end

    methods (Access = private)
        function grndObj = getSelectedGroundObj(app, ~)
            %             grndObjs = getappdata(handles.lvd_EditGroundObjectsGUI,'grndObjs');
            %
            %             ind = handles.groundObjListbox.Value;
            %             grndObj = grndObjs.getGroundObjAtInd(ind);
            grndObj = app.groundObjListbox.Value;
        end

        function wayPt = getSelectedWayPt(app, ~)
            wayPt = app.waypointsListbox.Value;
        end

        function handles = populateGUI(app, grndObjs, handles)
            app.populateGroundObjListbox();

            celBodyData = grndObjs.lvdData.celBodyData;
            populateBodiesCombo(celBodyData, handles.celestialBodyCombo);

            grndObj = getSelectedGroundObj(app, handles);
            updateGuiForGroundObj(app, grndObj, handles);
        end

        function populateGroundObjListbox(app)
            grndObjs = getappdata(app.lvd_EditGroundObjectsGUI,'grndObjs');
            [listboxStr, groundObjs] = grndObjs.getListboxStr();
            app.groundObjListbox.Items = listboxStr;
            app.groundObjListbox.ItemsData = groundObjs;

            if(numel(app.groundObjListbox.Value) == 0 && numel(groundObjs) > 0)
                app.groundObjListbox.Value = groundObjs(1);
            end

            app.setGrndObjDeleteButtonEnable([]);
        end

        function setGrndObjDeleteButtonEnable(app, ~)
            grndObjs = getappdata(app.lvd_EditGroundObjectsGUI,'grndObjs');
            numGrndObjs = grndObjs.getNumGroundObj();

            if(numGrndObjs > 0)
                app.removeGroundObjButton.Enable = 'on';
            else
                app.removeGroundObjButton.Enable = 'off';
            end
        end

        function setWayPtDeleteButtonEnable(app, handles)
            grndObj = getSelectedGroundObj(app, handles);

            if(not(isempty(grndObj)))
                numWayPts = grndObj.getNumWayPts();

                if(numWayPts > 1)
                    handles.removeWaypointButton.Enable = 'on';
                else
                    handles.removeWaypointButton.Enable = 'off';
                end
            else
                handles.removeWaypointButton.Enable = 'off';
            end
        end

        function toggleEnableForGuiElements(app, enableStr, handles)
            handles.groundObjListbox.Enable = enableStr;
            handles.removeGroundObjButton.Enable = enableStr;

            handles.groundObjectNameText.Enable = enableStr;
            handles.groundObjDescText.Enable = enableStr;

            handles.celestialBodyCombo.Enable = enableStr;

            lvdSetColorPickerEnable(app.lvd_EditGroundObjectsGUI, 'markerColor', enableStr);
            handles.groundObjMarkerShapeCombo.Enable = enableStr;
            lvdSetColorPickerEnable(app.lvd_EditGroundObjectsGUI, 'trkLineColor', enableStr);
            handles.groundObjLineSpecCombo.Enable = enableStr;

            handles.initialTimeText.Enable = enableStr;
            handles.extrapolateTimesCheckbox.Enable = enableStr;
            handles.loopGroundObjTrackCheckbox.Enable = enableStr;

            handles.waypointsListbox.Enable = enableStr;
            handles.moveWaypointDownButton.Enable = enableStr;
            handles.moveWaypointUpButton.Enable = enableStr;
            handles.addWaypointButton.Enable = enableStr;
            handles.removeWaypointButton.Enable = enableStr;

            handles.waypointLatText.Enable = enableStr;
            handles.waypointLongText.Enable = enableStr;
            handles.waypointAltText.Enable = enableStr;
            handles.timeToNextWaypointText.Enable = enableStr;
            handles.distToNextWaypointLabel.Enable = enableStr;
        end

        function updateDistanceLabel(app, handles)
            grndObj = getSelectedGroundObj(app, handles);
            wayPt = getSelectedWayPt(app, handles);

            wayPt2 = grndObj.getNextWaypt(wayPt);
            if(not(isempty(wayPt2)))
                wayPtDistStr = fullAccNum2Str(grndObj.getDistanceBetweenWayPts(wayPt, wayPt2));
            else
                wayPtDistStr = '--';
            end

            handles.distToNextWaypointLabel.String = wayPtDistStr;
        end

        function updateGuiForGroundObj(app, grndObj, handles)
            lvdSetupColorPicker(app.lvd_EditGroundObjectsGUI, app.GridLayout8, 2, 2, handles.groundObjColorCombo, lvd_colorSpecToRGB(grndObj.markerColor), 'The color of the ground object marker on the display.', 'markerColor', @(rgb) set(grndObj, 'markerColor', rgb));
            handles.groundObjMarkerShapeCombo.String = MarkerStyleEnum.getListboxStr();
            lvdSetupColorPicker(app.lvd_EditGroundObjectsGUI, app.GridLayout8, 2, 3, handles.groundObjLineColorCombo, lvd_colorSpecToRGB(grndObj.grdTrkLineColor), 'The color of the ground object track line on the display.', 'trkLineColor', @(rgb) set(grndObj, 'grdTrkLineColor', rgb));
            handles.groundObjLineSpecCombo.String = LineSpecEnum.getListboxStr();

            if(not(isempty(grndObj)))
                grndObjs = getappdata(handles.lvd_EditGroundObjectsGUI,'grndObjs');
                celBodyData = grndObjs.lvdData.celBodyData;

                handles.groundObjectNameText.String = grndObj.name;
                handles.groundObjDescText.String = grndObj.desc;

                [~, sortedBodyInfo] = ma_getSortedBodyNames(celBodyData);
                sortedBodyInfo = [sortedBodyInfo{:}];

                ind = find(grndObj.centralBodyInfo == sortedBodyInfo,1,'first');
                handles.celestialBodyCombo.Value = ind;

                
                handles.groundObjMarkerShapeCombo.Value = MarkerStyleEnum.getIndForName(grndObj.markerShape.name);
                
                handles.groundObjLineSpecCombo.Value = LineSpecEnum.getIndForName(grndObj.grdTrkLineSpec.name);

                handles.initialTimeText.String = fullAccNum2Str(grndObj.initialTime);
                handles.extrapolateTimesCheckbox.Value = double(grndObj.extrapolateTimes);
                handles.loopGroundObjTrackCheckbox.Value = double(grndObj.loopWayPts);

                app.populateGroundObjWayPtListbox();

                wayPt = getSelectedWayPt(app, handles);
                updateGuiForWayPt(app, wayPt, grndObj, handles);

                toggleEnableForGuiElements(app, 'on', handles);
            else
                handles.groundObjectNameText.String = '';
                handles.groundObjDescText.String = '';
                handles.initialTimeText.String = '';

                updateGuiForWayPt(app, LaunchVehicleGroundObjectWayPt.empty(1,0), grndObj, handles);
                toggleEnableForGuiElements(app, 'off', handles);
            end

            setWayPtDeleteButtonEnable(app, handles);
            setGrndObjDeleteButtonEnable(app, handles);
        end

        function populateGroundObjWayPtListbox(app)
            grndObj = app.getSelectedGroundObj();
            [listboxStr, wayPts] = grndObj.getWayListboxStr();
            app.waypointsListbox.Items = listboxStr;
            app.waypointsListbox.ItemsData = wayPts;

            if(numel(app.waypointsListbox.Value) == 0 && numel(wayPts) > 0)
                app.waypointsListbox.Value = wayPts(1);
            end

            if(numel(wayPts) > 1)
                app.removeWaypointButton.Enable = 'on';
            else
                app.removeWaypointButton.Enable = 'off';
            end
        end

        function updateGuiForWayPt(app, wayPt, ~, handles)
            if(not(isempty(wayPt)))
                handles.waypointLatText.String = fullAccNum2Str(rad2deg(wayPt.getLatitude()));
                handles.waypointLongText.String = fullAccNum2Str(rad2deg(wayPt.getLongitude()));
                handles.waypointAltText.String = fullAccNum2Str(wayPt.getAltitude());
                handles.timeToNextWaypointText.String = fullAccNum2Str(wayPt.getTimesToNextWaypt());

                updateDistanceLabel(app, handles);
            else
                handles.waypointLatText.String = '';
                handles.waypointLongText.String = '';
                handles.waypointAltText.String = '';
                handles.timeToNextWaypointText.String = '';
                handles.distToNextWaypointLabel.String = '';

                %                 handles.waypointsListbox.String = {};
                app.waypointsListbox.Items = {''};
                app.waypointsListbox.ItemsData = {''};
            end

            setWayPtDeleteButtonEnable(app, handles);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditGroundObjectsGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditGroundObjectsGUI (see VARARGIN)

            % Choose default command line output for lvd_EditGroundObjectsGUI
            handles.output = hObject;

            centerUIFigure(app.lvd_EditGroundObjectsGUI);
            applySelectedThemeToApp(app);

            grndObjs = varargin{1};
            setappdata(hObject,'grndObjs',grndObjs);

            handles = populateGUI(app, grndObjs, handles);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditGroundObjectsGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditGroundObjectsGUI);
        end

        % Button pushed function: addGroundObjButton
        function addGroundObjButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addGroundObjButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            grndObjs = getappdata(handles.lvd_EditGroundObjectsGUI,'grndObjs');
            celBodyData = grndObjs.lvdData.celBodyData;

            grndObj = LaunchVehicleGroundObject.getDefaultObj(celBodyData);
            grndObjs.addGroundObj(grndObj);

            app.populateGroundObjListbox();
            app.groundObjListbox.Value = grndObj;
%             handles.groundObjListbox.Value = grndObjs.getNumGroundObj();
            updateGuiForGroundObj(app, grndObj, handles);

            setGrndObjDeleteButtonEnable(app, handles);
        end

        % Button pushed function: addWaypointButton
        function addWaypointButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addWaypointButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            grndObj = getSelectedGroundObj(app, handles);

            initialTime = grndObj.initialTime;
            frameToUse = grndObj.centralBodyInfo.getBodyFixedFrame();
            wayPt = LaunchVehicleGroundObjectWayPt.getDefaultWayPt(initialTime, frameToUse);
            grndObj.addWaypoint(wayPt);

            app.populateGroundObjWayPtListbox();
            app.waypointsListbox.Value = wayPt;
%             handles.waypointsListbox.Value = grndObj.getNumWayPts();
            updateGuiForWayPt(app, wayPt, grndObj, handles);

            setWayPtDeleteButtonEnable(app, handles);
        end

        % Value changed function: celestialBodyCombo
        function celestialBodyCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to celestialBodyCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns celestialBodyCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from celestialBodyCombo
            grndObjs = getappdata(handles.lvd_EditGroundObjectsGUI,'grndObjs');
            celBodyData = grndObjs.lvdData.celBodyData;
            [~, sortedBodyInfo] = ma_getSortedBodyNames(celBodyData);

            grndObj = getSelectedGroundObj(app, handles);

            ind = hObject.Value;
            grndObj.centralBodyInfo = sortedBodyInfo{ind};
        end

        % Menu selected function: enterUTAsDateTimeMenu
        function enterUTAsDateTimeMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to enterUTAsDateTimeMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
%             secUT = enterUTAsDateTimeGUI(str2double(get(gco, 'String')));

            output2 = AppDesignerGUIOutput({-1});
            enterUTAsDateTimeGUI_App(str2double(get(gco, 'Value')), output2);
            secUT = output2.output{1};

            if(secUT >= 0)
                set(gco, 'String', fullAccNum2Str(secUT));

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                initialTimeText_Callback(app, evt);
            end
        end

        % Value changed function: extrapolateTimesCheckbox
        function extrapolateTimesCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to extrapolateTimesCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of extrapolateTimesCheckbox
            grndObj = getSelectedGroundObj(app, handles);
            grndObj.extrapolateTimes = logical(get(hObject,'Value'));
        end

        % Menu selected function: getUTFromKSPMenu
        function getUTFromKSPMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getUTFromKSPMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            secUT = readDoublesFromKSPTOTConnect('GetUT', '', true);
            if(secUT >= 0)
                set(gco, 'String', fullAccNum2Str(secUT));

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                initialTimeText_Callback(app, evt);
            end
        end

        % Value changed function: groundObjColorCombo
        function groundObjColorCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to groundObjColorCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns groundObjColorCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from groundObjColorCombo
            grndObj = getSelectedGroundObj(app, handles);

            contents = cellstr(get(hObject,'String'));
            str = contents{get(hObject,'Value')};

            grndObj.markerColor = ColorSpecEnum.getEnumForListboxStr(str);
        end

        % Value changed function: groundObjDescText
        function groundObjDescText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to groundObjDescText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of groundObjDescText as text
            %        str2double(get(hObject,'String')) returns contents of groundObjDescText as a double
            grndObj = getSelectedGroundObj(app, handles);
            grndObj.desc = get(hObject,'String');
        end

        % Value changed function: groundObjLineColorCombo
        function groundObjLineColorCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to groundObjLineColorCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns groundObjLineColorCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from groundObjLineColorCombo
            grndObj = getSelectedGroundObj(app, handles);

            contents = cellstr(get(hObject,'String'));
            str = contents{get(hObject,'Value')};

            grndObj.grdTrkLineColor = ColorSpecEnum.getEnumForListboxStr(str);
        end

        % Value changed function: groundObjLineSpecCombo
        function groundObjLineSpecCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to groundObjLineSpecCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns groundObjLineSpecCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from groundObjLineSpecCombo
            grndObj = getSelectedGroundObj(app, handles);

            contents = cellstr(get(hObject,'String'));
            str = contents{get(hObject,'Value')};

            grndObj.grdTrkLineSpec = LineSpecEnum.getEnumForListboxStr(str);
        end

        % Value changed function: groundObjListbox
        function groundObjListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to groundObjListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns groundObjListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from groundObjListbox
            grndObj = getSelectedGroundObj(app, handles);
            updateGuiForGroundObj(app, grndObj, handles);
        end

        % Value changed function: groundObjMarkerShapeCombo
        function groundObjMarkerShapeCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to groundObjMarkerShapeCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns groundObjMarkerShapeCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from groundObjMarkerShapeCombo
            grndObj = getSelectedGroundObj(app, handles);

            contents = cellstr(get(hObject,'String'));
            str = contents{get(hObject,'Value')};

            grndObj.markerShape = MarkerStyleEnum.getEnumForListboxStr(str);
        end

        % Value changed function: groundObjectNameText
        function groundObjectNameText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to groundObjectNameText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of groundObjectNameText as text
            %        str2double(get(hObject,'String')) returns contents of groundObjectNameText as a double
            grndObj = getSelectedGroundObj(app, handles);
            grndObj.name = get(hObject,'String');

            grndObjs = getappdata(handles.lvd_EditGroundObjectsGUI,'grndObjs');
            handles.groundObjListbox.String = grndObjs.getListboxStr();
        end

        % Value changed function: initialTimeText
        function initialTimeText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to initialTimeText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of initialTimeText as text
            %        str2double(get(hObject,'String')) returns contents of initialTimeText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            grndObj = getSelectedGroundObj(app, handles);

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Initial Time';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                grndObj.initialTime = value;
            else
                hObject.String = fullAccNum2Str(grndObj.initialTime);

                msgbox(errMsg,'Invalid Initial Time Value','error');
            end
        end

        % Value changed function: loopGroundObjTrackCheckbox
        function loopGroundObjTrackCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to loopGroundObjTrackCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of loopGroundObjTrackCheckbox
            grndObj = getSelectedGroundObj(app, handles);
            grndObj.loopWayPts = logical(get(hObject,'Value'));

            wayPt = getSelectedWayPt(app, handles);
            updateGuiForWayPt(app, wayPt, grndObj, handles);
        end

        % Button pushed function: moveWaypointDownButton
        function moveWaypointDownButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to moveWaypointDownButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            grndObj = getSelectedGroundObj(app, handles);

            wayPtNum = get(handles.waypointsListbox,'Value');
            grndObj.moveWayPtAtIndexDown(wayPtNum);

            if(wayPtNum < grndObj.getNumWayPts())
                set(handles.waypointsListbox,'Value',wayPtNum+1);
            end

            handles.waypointsListbox.String = grndObj.getWayListboxStr();
        end

        % Button pushed function: moveWaypointUpButton
        function moveWaypointUpButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to moveWaypointUpButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            grndObj = getSelectedGroundObj(app, handles);

            wayPtNum = get(handles.waypointsListbox,'Value');
            grndObj.moveWayPtAtIndexUp(wayPtNum);

            if(wayPtNum > 1)
                set(handles.waypointsListbox,'Value',wayPtNum-1);
            end

            handles.waypointsListbox.String = grndObj.getWayListboxStr();
        end

        % Button pushed function: removeGroundObjButton
        function removeGroundObjButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeGroundObjButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            grndObjs = getappdata(handles.lvd_EditGroundObjectsGUI,'grndObjs');

            grndObj = getSelectedGroundObj(app, handles);

            tf = grndObj.isInUse();
            if(tf == false)
                grndObjs.removeGroundObj(grndObj);
                numGrndObjs = grndObjs.getNumGroundObj();

                grndObjs.lvdData.viewSettings.removeGrdObjFromViewProfiles(grndObj);

                app.populateGroundObjListbox();

                if(numGrndObjs > 0 && handles.groundObjListbox.Value > numGrndObjs)
                    handles.groundObjListbox.Value = numGrndObjs;
                end

                grndObj = getSelectedGroundObj(app, handles);
                updateGuiForGroundObj(app, grndObj, handles);
                setGrndObjDeleteButtonEnable(app, handles);
            else
                uialert(app.lvd_EditGroundObjectsGUI, 'The selected ground object could not be deleted.  It is in use as a constraint or geometric point.  Remove ground object dependecies first.', 'Could Not Delete Ground Object', "Icon","error");
            end
        end

        % Button pushed function: removeWaypointButton
        function removeWaypointButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeWaypointButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            grndObj = getSelectedGroundObj(app, handles);

            wayPt = getSelectedWayPt(app, handles);
            grndObj.removeWaypoint(wayPt);
            numWayPts = grndObj.getNumWayPts();

            app.populateGroundObjWayPtListbox();

            if(handles.waypointsListbox.Value > numWayPts)
                handles.waypointsListbox.Value = numWayPts;
            end

            wayPt = getSelectedWayPt(app, handles);
            updateGuiForWayPt(app, wayPt, grndObj, handles);
            setWayPtDeleteButtonEnable(app, handles);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = {};
            if(isempty(errMsg))
                close(handles.lvd_EditGroundObjectsGUI);
            else
                msgbox(errMsg,'Errors were found while editing ground objects.','error');
            end
        end

        % Value changed function: timeToNextWaypointText
        function timeToNextWaypointText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to timeToNextWaypointText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of timeToNextWaypointText as text
            %        str2double(get(hObject,'String')) returns contents of timeToNextWaypointText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            wayPt = getSelectedWayPt(app, handles);

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Time to Next Waypoint';
            lb = 0.001;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                wayPt.timeToNextWayPt = value;

                grndObj = getSelectedGroundObj(app, handles);
                handles.waypointsListbox.String = grndObj.getWayListboxStr();
                updateDistanceLabel(app, handles);
            else
                hObject.String = fullAccNum2Str(wayPt.timeToNextWayPt);

                msgbox(errMsg,'Invalid Time to Next Waypoint Value','error');
            end
        end

        % Value changed function: waypointAltText
        function waypointAltText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to waypointAltText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of waypointAltText as text
            %        str2double(get(hObject,'String')) returns contents of waypointAltText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            wayPt = getSelectedWayPt(app, handles);

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Waypoint Altitude';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                wayPt.setAltitude(value);

                grndObj = getSelectedGroundObj(app, handles);
                handles.waypointsListbox.String = grndObj.getWayListboxStr();
                updateDistanceLabel(app, handles);
            else
                hObject.String = fullAccNum2Str(wayPt.getAltitude());

                msgbox(errMsg,'Invalid Waypoint Altitude Value','error');
            end
        end

        % Value changed function: waypointLatText
        function waypointLatText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to waypointLatText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of waypointLatText as text
            %        str2double(get(hObject,'String')) returns contents of waypointLatText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            wayPt = getSelectedWayPt(app, handles);

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Waypoint Latitude';
            lb = -90;
            ub = 90;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                wayPt.setLatitude(deg2rad(value));

                grndObj = getSelectedGroundObj(app, handles);
                handles.waypointsListbox.String = grndObj.getWayListboxStr();
                updateDistanceLabel(app, handles);
            else
                hObject.String = fullAccNum2Str(rad2deg(wayPt.getLatitude()));

                msgbox(errMsg,'Invalid Waypoint Latitude Value','error');
            end
        end

        % Value changed function: waypointLongText
        function waypointLongText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to waypointLongText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of waypointLongText as text
            %        str2double(get(hObject,'String')) returns contents of waypointLongText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            wayPt = getSelectedWayPt(app, handles);

            errMsg = {};

            value = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Waypoint Longitude';
            lb = -360;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                wayPt.setLongitude(deg2rad(value));

                grndObj = getSelectedGroundObj(app, handles);
                handles.waypointsListbox.String = grndObj.getWayListboxStr();
                updateDistanceLabel(app, handles);
            else
                hObject.String = fullAccNum2Str(rad2deg(wayPt.getLongitude()));

                msgbox(errMsg,'Invalid Waypoint Longitude Value','error');
            end
        end

        % Value changed function: waypointsListbox
        function waypointsListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to waypointsListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns waypointsListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from waypointsListbox
            grndObj = getSelectedGroundObj(app, handles);
            wayPt = getSelectedWayPt(app, handles);
            updateGuiForWayPt(app, wayPt, grndObj, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditGroundObjectsGUI = uifigure('Visible', 'off');
            app.lvd_EditGroundObjectsGUI.Name = 'Edit Ground Objects';
            app.lvd_EditGroundObjectsGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditGroundObjectsGUI.Tag = 'lvd_EditGroundObjectsGUI';
            app.GridLayout = uigridlayout(app.lvd_EditGroundObjectsGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Ground Objects';
            app.saveAndCloseButton = uibutton(app.GridLayout, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.addGroundObjButton = uibutton(app.GridLayout3, 'push');
            app.addGroundObjButton.ButtonPushedFcn = createCallbackFcn(app, @addGroundObjButton_Callback, true);
            app.addGroundObjButton.Tag = 'addGroundObjButton';
            app.addGroundObjButton.Icon = 'add.png';
            app.addGroundObjButton.Tooltip = 'Add a ground object.';
            app.addGroundObjButton.Text = 'Add Ground Object';
            app.removeGroundObjButton = uibutton(app.GridLayout3, 'push');
            app.removeGroundObjButton.ButtonPushedFcn = createCallbackFcn(app, @removeGroundObjButton_Callback, true);
            app.removeGroundObjButton.Tag = 'removeGroundObjButton';
            app.removeGroundObjButton.Icon = 'remove.png';
            app.removeGroundObjButton.Tooltip = 'Remove the currently selected ground object.';
            app.removeGroundObjButton.Text = 'Remove Ground Object';
            app.groundObjListbox = uilistbox(app.GridLayout3);
            app.groundObjListbox.Items = {'Listbox'};
            app.groundObjListbox.ValueChangedFcn = createCallbackFcn(app, @groundObjListbox_Callback, true);
            app.groundObjListbox.Tag = 'groundObjListbox';
            app.groundObjListbox.Tooltip = 'The list of all ground objects available in this mission case.  Ground objects may represent ground stations, rovers, aircraft, or anything else that moves or exists primarily on the surface of a celestial body.';
            app.groundObjListbox.Value = 'Listbox';
            app.EditGroundObjectPanel = uipanel(app.GridLayout2);
            app.EditGroundObjectPanel.Title = 'Edit Ground Object';
            app.GridLayout4 = uigridlayout(app.EditGroundObjectPanel);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.text3 = uilabel(app.GridLayout5);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Ground Object Name';
            app.groundObjectNameText = uieditfield(app.GridLayout5, 'text');
            app.groundObjectNameText.ValueChangedFcn = createCallbackFcn(app, @groundObjectNameText_Callback, true);
            app.groundObjectNameText.Tag = 'groundObjectNameText';
            app.groundObjectNameText.Tooltip = 'The name of the ground object.';
            app.groundObjectNameText.Value = 'Untitled Ground Object';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.text4 = uilabel(app.GridLayout6);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Ground Object Description';
            app.groundObjDescText = uitextarea(app.GridLayout6);
            app.groundObjDescText.ValueChangedFcn = createCallbackFcn(app, @groundObjDescText_Callback, true);
            app.groundObjDescText.Tag = 'groundObjDescText';
            app.groundObjDescText.Tooltip = 'A description of the ground object.';
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.text18 = uilabel(app.GridLayout7);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Ground Object Celestial Body';
            app.celestialBodyCombo = uidropdown(app.GridLayout7);
            app.celestialBodyCombo.Items = {'Pop-up Menu'};
            app.celestialBodyCombo.ValueChangedFcn = createCallbackFcn(app, @celestialBodyCombo_Callback, true);
            app.celestialBodyCombo.Tag = 'celestialBodyCombo';
            app.celestialBodyCombo.Tooltip = 'The celestial body on which the ground object exists.';
            app.celestialBodyCombo.Value = 'Pop-up Menu';
            app.uipanel6 = uipanel(app.GridLayout4);
            app.uipanel6.Title = 'Display';
            app.uipanel6.Tag = 'uipanel6';
            app.GridLayout8 = uigridlayout(app.uipanel6);
            app.groundObjLineColorCombo = uidropdown(app.GridLayout8);
            app.groundObjLineColorCombo.Items = {};
            app.groundObjLineColorCombo.ValueChangedFcn = createCallbackFcn(app, @groundObjLineColorCombo_Callback, true);
            app.groundObjLineColorCombo.Tag = 'groundObjLineColorCombo';
            app.groundObjLineColorCombo.Tooltip = 'The color of the ground object track line on the display.';
            app.groundObjLineColorCombo.Value = {};
            app.groundObjMarkerShapeCombo = uidropdown(app.GridLayout8);
            app.groundObjMarkerShapeCombo.Items = {};
            app.groundObjMarkerShapeCombo.ValueChangedFcn = createCallbackFcn(app, @groundObjMarkerShapeCombo_Callback, true);
            app.groundObjMarkerShapeCombo.Tag = 'groundObjMarkerShapeCombo';
            app.groundObjMarkerShapeCombo.Tooltip = 'The shape of the ground object marker on the display.';
            app.groundObjMarkerShapeCombo.Value = {};
            app.groundObjLineSpecCombo = uidropdown(app.GridLayout8);
            app.groundObjLineSpecCombo.Items = {};
            app.groundObjLineSpecCombo.ValueChangedFcn = createCallbackFcn(app, @groundObjLineSpecCombo_Callback, true);
            app.groundObjLineSpecCombo.Tag = 'groundObjLineSpecCombo';
            app.groundObjLineSpecCombo.Tooltip = 'The style of the line of the ground object track line on the display.';
            app.groundObjLineSpecCombo.Value = {};
            app.text5 = uilabel(app.GridLayout8);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Ground Object Marker';
            app.text6 = uilabel(app.GridLayout8);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Ground Object Track Line ';
            app.groundObjColorCombo = uidropdown(app.GridLayout8);
            app.groundObjColorCombo.Items = {};
            app.groundObjColorCombo.ValueChangedFcn = createCallbackFcn(app, @groundObjColorCombo_Callback, true);
            app.groundObjColorCombo.Tag = 'groundObjColorCombo';
            app.groundObjColorCombo.Tooltip = 'The color of the ground object marker on the display.';
            app.groundObjColorCombo.Value = {};
            app.Image = uiimage(app.GridLayout8);
            app.Image.ImageSource = 'color_wheel.png';
            app.Image2 = uiimage(app.GridLayout8);
            app.Image2.ImageSource = 'line.png';
            app.uipanel4 = uipanel(app.GridLayout4);
            app.uipanel4.Title = 'Time';
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout9 = uigridlayout(app.uipanel4);
            app.text7 = uilabel(app.GridLayout9);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Initial Time';
            app.extrapolateTimesCheckbox = uicheckbox(app.GridLayout9);
            app.extrapolateTimesCheckbox.ValueChangedFcn = createCallbackFcn(app, @extrapolateTimesCheckbox_Callback, true);
            app.extrapolateTimesCheckbox.Tag = 'extrapolateTimesCheckbox';
            app.extrapolateTimesCheckbox.Tooltip = 'If checked, this object will be in existance for all time.';
            app.extrapolateTimesCheckbox.Text = 'Extrapolate Times';
            app.loopGroundObjTrackCheckbox = uicheckbox(app.GridLayout9);
            app.loopGroundObjTrackCheckbox.ValueChangedFcn = createCallbackFcn(app, @loopGroundObjTrackCheckbox_Callback, true);
            app.loopGroundObjTrackCheckbox.Tag = 'loopGroundObjTrackCheckbox';
            app.loopGroundObjTrackCheckbox.Tooltip = 'If checked, the object will proceed from the final waypoint back to the first waypoint.  If unchecked, the object will return to the first waypoint in reverse order.';
            app.loopGroundObjTrackCheckbox.Text = 'Loop Ground Object Track';
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.text8 = uilabel(app.GridLayout10);
            app.text8.Tag = 'text8';
            app.text8.Text = 'sec';
            app.initialTimeText = uieditfield(app.GridLayout10, 'text');
            app.initialTimeText.ValueChangedFcn = createCallbackFcn(app, @initialTimeText_Callback, true);
            app.initialTimeText.Tag = 'initialTimeText';
            app.initialTimeText.Tooltip = 'The universal time at which this object comes into existance.';
            app.initialTimeText.Value = '0.0';
            app.uipanel3 = uipanel(app.GridLayout4);
            app.uipanel3.Title = 'Edit Way Points';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout11 = uigridlayout(app.uipanel3);
            app.uipanel5 = uipanel(app.GridLayout11);
            app.uipanel5.Title = 'Way Point Details';
            app.uipanel5.Tag = 'uipanel5';
            app.GridLayout12 = uigridlayout(app.uipanel5);
            app.text11 = uilabel(app.GridLayout12);
            app.text11.Tag = 'text11';
            app.text11.Text = 'deg';
            app.text13 = uilabel(app.GridLayout12);
            app.text13.Tag = 'text13';
            app.text13.Text = 'deg';
            app.text15 = uilabel(app.GridLayout12);
            app.text15.Tag = 'text15';
            app.text15.Text = 'km';
            app.text17 = uilabel(app.GridLayout12);
            app.text17.Tag = 'text17';
            app.text17.Text = 'sec';
            app.text10000 = uilabel(app.GridLayout12);
            app.text10000.Tag = 'text10000';
            app.text10000.Text = 'km';
            app.text10 = uilabel(app.GridLayout12);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Latitude';
            app.text12 = uilabel(app.GridLayout12);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Longitude';
            app.text14 = uilabel(app.GridLayout12);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Altitude';
            app.text16 = uilabel(app.GridLayout12);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Time to Next Way Point';
            app.text19 = uilabel(app.GridLayout12);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Distance to Next Way Point';
            app.waypointLatText = uieditfield(app.GridLayout12, 'text');
            app.waypointLatText.ValueChangedFcn = createCallbackFcn(app, @waypointLatText_Callback, true);
            app.waypointLatText.Tag = 'waypointLatText';
            app.waypointLatText.Tooltip = 'The latitude of the selected waypoint.';
            app.waypointLatText.Value = '0.0';
            app.waypointLongText = uieditfield(app.GridLayout12, 'text');
            app.waypointLongText.ValueChangedFcn = createCallbackFcn(app, @waypointLongText_Callback, true);
            app.waypointLongText.Tag = 'waypointLongText';
            app.waypointLongText.Tooltip = 'The longitude of the selected waypoint.';
            app.waypointLongText.Value = '0.0';
            app.waypointAltText = uieditfield(app.GridLayout12, 'text');
            app.waypointAltText.ValueChangedFcn = createCallbackFcn(app, @waypointAltText_Callback, true);
            app.waypointAltText.Tag = 'waypointAltText';
            app.waypointAltText.Tooltip = 'The altitude of the selected waypoint.';
            app.waypointAltText.Value = '0.0';
            app.timeToNextWaypointText = uieditfield(app.GridLayout12, 'text');
            app.timeToNextWaypointText.ValueChangedFcn = createCallbackFcn(app, @timeToNextWaypointText_Callback, true);
            app.timeToNextWaypointText.Tag = 'timeToNextWaypointText';
            app.timeToNextWaypointText.Tooltip = 'The travel time between this waypoint and the next waypoint.  If looped, may refer to transit time between the final and first waypoints.  If unlooped, does not apply to final waypoint.';
            app.timeToNextWaypointText.Value = '0.0';
            app.distToNextWaypointLabel = uilabel(app.GridLayout12);
            app.distToNextWaypointLabel.Tag = 'distToNextWaypointLabel';
            app.distToNextWaypointLabel.Tooltip = 'Shows the "as the crow flies" distance over ground to the next waypoint, if it exists.';
            app.distToNextWaypointLabel.Text = '0.0';
            app.GridLayout13 = uigridlayout(app.GridLayout11);
            app.waypointsListbox = uilistbox(app.GridLayout13);
            app.waypointsListbox.Items = {};
            app.waypointsListbox.ValueChangedFcn = createCallbackFcn(app, @waypointsListbox_Callback, true);
            app.waypointsListbox.Tag = 'waypointsListbox';
            app.waypointsListbox.Tooltip = 'The list of waypoints the object travels to.  There must be at least one waypoint.  If only one, the object will be stationary on the surface of the selected body.';
            app.waypointsListbox.Value = {};
            app.addWaypointButton = uibutton(app.GridLayout13, 'push');
            app.addWaypointButton.ButtonPushedFcn = createCallbackFcn(app, @addWaypointButton_Callback, true);
            app.addWaypointButton.Tag = 'addWaypointButton';
            app.addWaypointButton.Icon = 'add.png';
            app.addWaypointButton.Tooltip = 'Add a way point to the list.';
            app.addWaypointButton.Text = 'Add Way Point';
            app.removeWaypointButton = uibutton(app.GridLayout13, 'push');
            app.removeWaypointButton.ButtonPushedFcn = createCallbackFcn(app, @removeWaypointButton_Callback, true);
            app.removeWaypointButton.Tag = 'removeWaypointButton';
            app.removeWaypointButton.Icon = 'remove.png';
            app.removeWaypointButton.Tooltip = 'Remove the selected waypoint from the list.';
            app.removeWaypointButton.Text = 'Remove Way Point';
            app.GridLayout14 = uigridlayout(app.GridLayout13);
            app.moveWaypointDownButton = uibutton(app.GridLayout14, 'push');
            app.moveWaypointDownButton.ButtonPushedFcn = createCallbackFcn(app, @moveWaypointDownButton_Callback, true);
            app.moveWaypointDownButton.Tag = 'moveWaypointDownButton';
            app.moveWaypointDownButton.Tooltip = 'Move the selected waypoint up in the list.';
            app.moveWaypointDownButton.Text = '↑';
            app.moveWaypointUpButton = uibutton(app.GridLayout14, 'push');
            app.moveWaypointUpButton.ButtonPushedFcn = createCallbackFcn(app, @moveWaypointUpButton_Callback, true);
            app.moveWaypointUpButton.Tag = 'moveWaypointUpButton';
            app.moveWaypointUpButton.Tooltip = 'Move the selected waypoint down in the list.';
            app.moveWaypointUpButton.Text = '↓';
            app.initialTimeContextMenu = uicontextmenu(app.lvd_EditGroundObjectsGUI);
            app.initialTimeContextMenu.Tag = 'initialTimeContextMenu';
            app.enterUTAsDateTimeMenu = uimenu(app.initialTimeContextMenu);
            app.enterUTAsDateTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @enterUTAsDateTimeMenu_Callback, true);
            app.enterUTAsDateTimeMenu.Text = 'Enter UT as Date/Time';
            app.enterUTAsDateTimeMenu.Tag = 'enterUTAsDateTimeMenu';
            app.getUTFromKSPMenu = uimenu(app.initialTimeContextMenu);
            app.getUTFromKSPMenu.MenuSelectedFcn = createCallbackFcn(app, @getUTFromKSPMenu_Callback, true);
            app.getUTFromKSPMenu.Text = 'Get UT From KSP';
            app.getUTFromKSPMenu.Tag = 'getUTFromKSPMenu';
            % Assign app.initialTimeContextMenu
            app.initialTimeText.ContextMenu = app.initialTimeContextMenu;
            % Show the figure after all components are created
            app.lvd_EditGroundObjectsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditGroundObjectsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditGroundObjectsGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditGroundObjectsGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditGroundObjectsGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditGroundObjectsGUI)
        end
    end
end