classdef lvd_Console_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LaunchVehicleDesignerConsoleUIFigure  matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        Panel            matlab.ui.container.Panel
        GridLayout4      matlab.ui.container.GridLayout
        VariableTable    matlab.ui.control.Table
        GridLayout2      matlab.ui.container.GridLayout
        GridLayout3      matlab.ui.container.GridLayout
        ClearButton      matlab.ui.control.Button
        EnterButton      matlab.ui.control.Button
        Label            matlab.ui.control.Label
        CommandLineText  matlab.ui.control.EditField
        OutputTextArea   matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        lvdDataP LvdData
        vars containers.Map

        cmdList string
        cmdListInd(1,1) double
    end
    
    methods (Access = private)
        function executeConsoleCmd(app)
            varKeys = app.vars.keys;
            for(i=1:length(varKeys))
                varKey = varKeys{i};
                evalc(sprintf("%s = app.vars('%s')", varKey, varKey));
            end

            cmd = app.CommandLineText.Value;
            if(isempty(cmd))
                return;
            end

            preExecVars = who();

            try
                [result] = evalc(cmd);
            catch ME
                result = sprintf('ERROR: %s', ME.message);
            end

            app.cmdList(end+1) = cmd;
            app.cmdListInd = length(app.cmdList)+1;

            postExecVars = who();
            preExecVars = vertcat(preExecVars,{'preExecVars';'result';'ME'});
            newVarNames = setdiff(postExecVars, preExecVars);

            for(i=1:length(postExecVars))
                varName = postExecVars{i};
                if(app.vars.isKey(varName))
                    app.vars(varName) = eval(varName);
                end
            end

            for(i=1:length(newVarNames)) %#ok<*NO4LP> 
                newVarName = newVarNames{i};
                [~,app.vars(newVarName)] = evalc(newVarName);
            end

            result = eraseTags(strip(string(result)));

            if(result.strlength > 0)
                result = sprintf("%s\n", result);
            end
            
            app.OutputTextArea.Value = [string(app.OutputTextArea.Value); ...
                                        sprintf(">> %s\n%s", cmd, result)];

            scroll(app.OutputTextArea, 'bottom');

            app.updateVarTable();
            app.CommandLineText.Value = '';
        end

        function updateVarTable(app)
            removeStyle(app.VariableTable);

            varNames = app.vars.keys;
            numVars = numel(varNames);

            tableData = cell(numVars, 2);
            app.VariableTable.Data = tableData;

            for(i=1:length(varNames))
                varName = varNames{i};
                varData = app.vars(varName);

                tableData{i,1} = char(varName);

                if(isnumeric(varData))
                    str = eraseTags(strip(formattedDisplayText(varData)));
                    if(numel(varData) > 1)
                        str = regexprep(str,'[\n\r]+',' ');
                        str = sprintf("[%s]", str);
                    end

                    tableData{i,2} = char(str);
                    iconStr = 'numeric.png';

                elseif(isobject(varData))
                    tableData{i,2} = char(sprintf("%s", class(varData)));
                    iconStr = '3dBlueBox.png';

                else
                    tableData{i,2} = char(eraseTags(strip(formattedDisplayText(varData))));
                    iconStr = 'none';
                end

                s = uistyle("Icon",iconStr, 'IconAlignment','leftmargin');
                addStyle(app.VariableTable,s, "cell",[i,1]);
            end

            app.VariableTable.Data = tableData;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, lvdData)
            app.lvdDataP = lvdData;
            app.vars = containers.Map('KeyType','char','ValueType','any');
            app.vars('lvdData') = lvdData;

            centerUIFigure(app.LaunchVehicleDesignerConsoleUIFigure);
            applySelectedThemeToApp(app);
            
            movegui(app.LaunchVehicleDesignerConsoleUIFigure, "center");
            app.updateVarTable();
            focus(app.CommandLineText);
        end

        % Value changed function: CommandLineText
        function CommandLineTextValueChanged(app, event)
            app.executeConsoleCmd();
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            app.executeConsoleCmd();
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.OutputTextArea.Value = '';
        end

        % Window key press function: LaunchVehicleDesignerConsoleUIFigure
        function LaunchVehicleDesignerConsoleUIFigureWindowKeyPress(app, event)
            key = event.Key;
            co = app.LaunchVehicleDesignerConsoleUIFigure.CurrentObject;

            if(length(app.cmdList) > 0 && not(isempty(co)) && co == app.CommandLineText) %#ok<ISMT> 
                switch key
                    case 'uparrow'
                        if(app.cmdListInd > 1)
                            app.cmdListInd = app.cmdListInd - 1;
                        end

                    case 'downarrow'
                        if(app.cmdListInd < length(app.cmdList))
                            app.cmdListInd = app.cmdListInd + 1;
                        end

                    case {'enter','return'}
                        app.executeConsoleCmd();
                        return;

                    otherwise
                        app.cmdListInd = length(app.cmdList);
                        return;
                end

                app.CommandLineText.Value = app.cmdList(app.cmdListInd);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LaunchVehicleDesignerConsoleUIFigure = uifigure('Visible', 'off');
            app.LaunchVehicleDesignerConsoleUIFigure.Name = 'Launch Vehicle Designer Console';
            app.LaunchVehicleDesignerConsoleUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.LaunchVehicleDesignerConsoleUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @LaunchVehicleDesignerConsoleUIFigureWindowKeyPress, true);
            app.GridLayout = uigridlayout(app.LaunchVehicleDesignerConsoleUIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.Panel);
            app.GridLayout2 = uigridlayout(app.GridLayout4);
            app.OutputTextArea = uitextarea(app.GridLayout2);
            app.OutputTextArea.Editable = 'off';
            app.OutputTextArea.Tooltip = {'The console output window.  Commands and their output show up here.'};
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.CommandLineText = uieditfield(app.GridLayout3, 'text');
            app.CommandLineText.ValueChangedFcn = createCallbackFcn(app, @CommandLineTextValueChanged, true);
            app.CommandLineText.Tooltip = {'The LVD command line.  Enter your valid MATLAB command here and then push Enter to execute it. '};
            app.CommandLineText.Placeholder = 'Enter MATLAB commands here...';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '>>';
            app.EnterButton = uibutton(app.GridLayout3, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Tooltip = {'Executes the command entered in the command line to the left.'};
            app.EnterButton.Text = 'Enter';
            app.ClearButton = uibutton(app.GridLayout3, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Tooltip = {'Clears the console window text.'};
            app.ClearButton.Text = 'Clear';
            app.VariableTable = uitable(app.GridLayout4);
            app.VariableTable.ColumnName = {'Variable'; 'Data'};
            app.VariableTable.RowName = {};
            app.VariableTable.Tooltip = {'The list of variables currently available to you.  "lvdData" is the data of the currently loaded LVD case file.  Other variables you create show up here.  Entering a variable name into the command line and pushing Enter will display the contents of that variable in more detail. '};
            % Show the figure after all components are created
            app.LaunchVehicleDesignerConsoleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_Console_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.LaunchVehicleDesignerConsoleUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.LaunchVehicleDesignerConsoleUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LaunchVehicleDesignerConsoleUIFigure)
        end
    end
end