classdef lvd_CreateContinuityConstraintsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CreateContinuityConstraintsUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        SaveAndCloseButton         matlab.ui.control.Button
        GridLayout2                matlab.ui.container.GridLayout
        ParametersPanel            matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        TankMassesLabel            matlab.ui.control.Label
        AttitudeLabel              matlab.ui.control.Label
        VelocityLabel              matlab.ui.control.Label
        PositionLabel              matlab.ui.control.Label
        TimeLabel                  matlab.ui.control.Label
        TankMassesScaleFactorText  matlab.ui.control.EditField
        AttScaleFactorText         matlab.ui.control.EditField
        VelocityScaleFactorText    matlab.ui.control.EditField
        PositionScaleFactorText    matlab.ui.control.EditField
        TimeScaleFactorText        matlab.ui.control.EditField
        text6_5                    matlab.ui.control.Label
        AttitudeCheckbox           matlab.ui.control.CheckBox
        text6_4                    matlab.ui.control.Label
        text6_3                    matlab.ui.control.Label
        text6_2                    matlab.ui.control.Label
        text6                      matlab.ui.control.Label
        TankMassesCheckbox         matlab.ui.control.CheckBox
        VelocityCheckbox           matlab.ui.control.CheckBox
        PositionCheckbox           matlab.ui.control.CheckBox
        TimeCheckBox               matlab.ui.control.CheckBox
        ScaleFactorLabel           matlab.ui.control.Label
        EventsPanel                matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        Event1Label                matlab.ui.control.Label
        Event2Combo                matlab.ui.control.DropDown
        Event1Combo                matlab.ui.control.DropDown
        Event2Label                matlab.ui.control.Label
        titleText                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        lvdData LvdData 
        output AppDesignerGUIOutput
    end
    
    methods (Access = private)
        function populateGUI(app, selEvent)
            [listboxStr, events] = app.lvdData.script.getListboxStr();
            
            app.Event1Combo.Items = listboxStr;
            app.Event1Combo.ItemsData = events;
            app.Event1Combo.Value = selEvent;
            
            app.Event2Combo.Items = listboxStr;
            app.Event2Combo.ItemsData = events;
            
            app.updateUi();
        end
        
        function updateUi(app)
            if(app.TimeCheckBox.Value)
                app.TimeScaleFactorText.Enable = 'on';
            else
                app.TimeScaleFactorText.Enable = 'off';
            end
            
            if(app.PositionCheckbox.Value)
                app.PositionScaleFactorText.Enable = 'on';
            else
                app.PositionScaleFactorText.Enable = 'off';
            end
            
            if(app.VelocityCheckbox.Value)
                app.VelocityScaleFactorText.Enable = 'on';
            else
                app.VelocityScaleFactorText.Enable = 'off';
            end
            
            if(app.AttitudeCheckbox.Value)
                app.AttScaleFactorText.Enable = 'on';
            else
                app.AttScaleFactorText.Enable = 'off';
            end
            
            if(app.TankMassesCheckbox.Value)
                app.TankMassesScaleFactorText.Enable = 'on';
            else
                app.TankMassesScaleFactorText.Enable = 'off';
            end
        end
        
        function saveAndClose(app)
            event1 = app.Event1Combo.Value;
            event2 = app.Event2Combo.Value;
            
            useTime = app.TimeCheckBox.Value;
            timeScale = str2double(app.TimeScaleFactorText.Value);
            
            usePos = app.PositionCheckbox.Value;
            posScale = str2double(app.PositionScaleFactorText.Value);
            
            useVel = app.VelocityCheckbox.Value;
            velScale = str2double(app.VelocityScaleFactorText.Value);
            
            useAtt = app.AttitudeCheckbox.Value;
            attScale = str2double(app.AttScaleFactorText.Value);
            
            useTankMass = app.TankMassesCheckbox.Value;
            tankMassScale = str2double(app.TankMassesScaleFactorText.Value);
            
            app.lvdData.createContinuityConstraints(event1, event2, [], useTime, timeScale, usePos, posScale, useVel, velScale, useAtt, attScale, useTankMass, tankMassScale);

            app.output.output = {true};
            close(app.CreateContinuityConstraintsUIFigure)
        end
        
        function errMsg = validateInputs(app)
            errMsg = {};
            
            if(app.Event1Combo.Value == app.Event2Combo.Value)
                errMsg{end+1} = 'Event 1 and Event 2 must be different.';
            end
            
            val = str2double(app.TimeScaleFactorText.Value);
            enteredStr = app.TimeScaleFactorText.Value;
            numberName = 'Time Scale Factor';
            lb = 1E-8;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
            
            val = str2double(app.PositionScaleFactorText.Value);
            enteredStr = app.PositionScaleFactorText.Value;
            numberName = 'Position Scale Factor';
            lb = 1E-8;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
            
            val = str2double(app.VelocityScaleFactorText.Value);
            enteredStr = app.VelocityScaleFactorText.Value;
            numberName = 'Velocity Scale Factor';
            lb = 1E-8;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
            
            val = str2double(app.AttScaleFactorText.Value);
            enteredStr = app.AttScaleFactorText.Value;
            numberName = 'Attitude Scale Factor';
            lb = 1E-8;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
            
            val = str2double(app.TankMassesScaleFactorText.Value);
            enteredStr = app.TankMassesScaleFactorText.Value;
            numberName = 'Tank Mass Scale Factor';
            lb = 1E-8;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(val, numberName, lb, ub, isInt, errMsg, enteredStr);
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, lvdData, selEvent, output)
            arguments
                app(1,1) lvd_CreateContinuityConstraintsGUI_App
                lvdData(1,1) LvdData
                selEvent(1,1) LaunchVehicleEvent
                output(1,1) AppDesignerGUIOutput
            end 
            
            centerUIFigure(app.CreateContinuityConstraintsUIFigure);
            applySelectedThemeToApp(app);
            
            app.lvdData = lvdData;
            app.output = output;
            
            app.populateGUI(selEvent);
            
            uiwait(app.CreateContinuityConstraintsUIFigure);
        end

        % Value changed function: TimeCheckBox
        function TimeCheckBoxValueChanged(app, event)
            app.updateUi();
        end

        % Value changed function: PositionCheckbox
        function PositionCheckboxValueChanged(app, event)
            app.updateUi();
        end

        % Value changed function: VelocityCheckbox
        function VelocityCheckboxValueChanged(app, event)
            app.updateUi();
        end

        % Value changed function: AttitudeCheckbox
        function AttitudeCheckboxValueChanged(app, event)
            app.updateUi();
        end

        % Value changed function: TankMassesCheckbox
        function TankMassesCheckboxValueChanged(app, event)
            app.updateUi();
        end

        % Value changed function: TimeScaleFactorText
        function TimeScaleFactorTextValueChanged(app, event)
            newInput = attemptStrEval(app.TimeScaleFactorText.Value);
            app.TimeScaleFactorText.Value = newInput;
        end

        % Value changed function: PositionScaleFactorText
        function PositionScaleFactorTextValueChanged(app, event)
            newInput = attemptStrEval(app.PositionScaleFactorText.Value);
            app.PositionScaleFactorText.Value = newInput;
        end

        % Value changed function: VelocityScaleFactorText
        function VelocityScaleFactorTextValueChanged(app, event)
            newInput = attemptStrEval(app.VelocityScaleFactorText.Value);
            app.VelocityScaleFactorText.Value = newInput;
        end

        % Value changed function: AttScaleFactorText
        function AttScaleFactorTextValueChanged(app, event)
            newInput = attemptStrEval(app.AttScaleFactorText.Value);
            app.AttScaleFactorText.Value = newInput;
        end

        % Value changed function: TankMassesScaleFactorText
        function TankMassesScaleFactorTextValueChanged(app, event)
            newInput = attemptStrEval(app.TankMassesScaleFactorText.Value);
            app.TankMassesScaleFactorText.Value = newInput;
        end

        % Button pushed function: SaveAndCloseButton
        function SaveAndCloseButtonPushed(app, event)
            errMsg = validateInputs(app);
        
            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.CreateContinuityConstraintsUIFigure, errMsg, 'Invalid Inputs', "Icon","error");
            end
        end

        % Close request function: CreateContinuityConstraintsUIFigure
        function CreateContinuityConstraintsUIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CreateContinuityConstraintsUIFigure = uifigure('Visible', 'off');
            app.CreateContinuityConstraintsUIFigure.Name = 'Create Continuity Constraints';
            app.CreateContinuityConstraintsUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.CreateContinuityConstraintsUIFigure.CloseRequestFcn = createCallbackFcn(app, @CreateContinuityConstraintsUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.CreateContinuityConstraintsUIFigure);
            app.titleText = uilabel(app.GridLayout);
            app.titleText.Tag = 'titleText';
            app.titleText.Text = 'Create Continuity Constraints';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.EventsPanel = uipanel(app.GridLayout2);
            app.EventsPanel.Title = 'Events';
            app.GridLayout3 = uigridlayout(app.EventsPanel);
            app.Event2Label = uilabel(app.GridLayout3);
            app.Event2Label.Text = 'Event 2';
            app.Event1Combo = uidropdown(app.GridLayout3);
            app.Event2Combo = uidropdown(app.GridLayout3);
            app.Event1Label = uilabel(app.GridLayout3);
            app.Event1Label.Text = 'Event 1';
            app.ParametersPanel = uipanel(app.GridLayout2);
            app.ParametersPanel.Title = 'Parameters';
            app.GridLayout4 = uigridlayout(app.ParametersPanel);
            app.ScaleFactorLabel = uilabel(app.GridLayout4);
            app.ScaleFactorLabel.Text = 'Scale Factor';
            app.TimeCheckBox = uicheckbox(app.GridLayout4);
            app.TimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @TimeCheckBoxValueChanged, true);
            app.TimeCheckBox.Text = '';
            app.TimeCheckBox.Value = true;
            app.PositionCheckbox = uicheckbox(app.GridLayout4);
            app.PositionCheckbox.ValueChangedFcn = createCallbackFcn(app, @PositionCheckboxValueChanged, true);
            app.PositionCheckbox.Text = '';
            app.PositionCheckbox.Value = true;
            app.VelocityCheckbox = uicheckbox(app.GridLayout4);
            app.VelocityCheckbox.ValueChangedFcn = createCallbackFcn(app, @VelocityCheckboxValueChanged, true);
            app.VelocityCheckbox.Text = '';
            app.VelocityCheckbox.Value = true;
            app.TankMassesCheckbox = uicheckbox(app.GridLayout4);
            app.TankMassesCheckbox.ValueChangedFcn = createCallbackFcn(app, @TankMassesCheckboxValueChanged, true);
            app.TankMassesCheckbox.Text = '';
            app.text6 = uilabel(app.GridLayout4);
            app.text6.Tag = 'text6';
            app.text6.Text = 'sec';
            app.text6_2 = uilabel(app.GridLayout4);
            app.text6_2.Tag = 'text6';
            app.text6_2.Text = 'km';
            app.text6_3 = uilabel(app.GridLayout4);
            app.text6_3.Tag = 'text6';
            app.text6_3.Text = 'km/s';
            app.text6_4 = uilabel(app.GridLayout4);
            app.text6_4.Tag = 'text6';
            app.text6_4.Text = 'mT';
            app.AttitudeCheckbox = uicheckbox(app.GridLayout4);
            app.AttitudeCheckbox.ValueChangedFcn = createCallbackFcn(app, @AttitudeCheckboxValueChanged, true);
            app.AttitudeCheckbox.Text = '';
            app.text6_5 = uilabel(app.GridLayout4);
            app.text6_5.Tag = 'text6';
            app.text6_5.Text = 'deg';
            app.TimeScaleFactorText = uieditfield(app.GridLayout4, 'text');
            app.TimeScaleFactorText.ValueChangedFcn = createCallbackFcn(app, @TimeScaleFactorTextValueChanged, true);
            app.TimeScaleFactorText.Value = '1.0';
            app.PositionScaleFactorText = uieditfield(app.GridLayout4, 'text');
            app.PositionScaleFactorText.ValueChangedFcn = createCallbackFcn(app, @PositionScaleFactorTextValueChanged, true);
            app.PositionScaleFactorText.Value = '1.0';
            app.VelocityScaleFactorText = uieditfield(app.GridLayout4, 'text');
            app.VelocityScaleFactorText.ValueChangedFcn = createCallbackFcn(app, @VelocityScaleFactorTextValueChanged, true);
            app.VelocityScaleFactorText.Value = '1.0';
            app.AttScaleFactorText = uieditfield(app.GridLayout4, 'text');
            app.AttScaleFactorText.ValueChangedFcn = createCallbackFcn(app, @AttScaleFactorTextValueChanged, true);
            app.AttScaleFactorText.Value = '1.0';
            app.TankMassesScaleFactorText = uieditfield(app.GridLayout4, 'text');
            app.TankMassesScaleFactorText.ValueChangedFcn = createCallbackFcn(app, @TankMassesScaleFactorTextValueChanged, true);
            app.TankMassesScaleFactorText.Value = '1.0';
            app.TimeLabel = uilabel(app.GridLayout4);
            app.TimeLabel.Text = 'Time';
            app.PositionLabel = uilabel(app.GridLayout4);
            app.PositionLabel.Text = 'Position';
            app.VelocityLabel = uilabel(app.GridLayout4);
            app.VelocityLabel.Text = 'Velocity';
            app.AttitudeLabel = uilabel(app.GridLayout4);
            app.AttitudeLabel.Text = 'Attitude';
            app.TankMassesLabel = uilabel(app.GridLayout4);
            app.TankMassesLabel.Text = 'Tank Masses';
            app.SaveAndCloseButton = uibutton(app.GridLayout, 'push');
            app.SaveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAndCloseButtonPushed, true);
            app.SaveAndCloseButton.Icon = 'save-file.png';
            app.SaveAndCloseButton.Text = 'Save And Close';
            % Show the figure after all components are created
            app.CreateContinuityConstraintsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_CreateContinuityConstraintsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CreateContinuityConstraintsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.CreateContinuityConstraintsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CreateContinuityConstraintsUIFigure)
        end
    end
end