classdef lvd_EditDragPropertiesGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditDragPropertiesGUI    matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        uipanel1                     matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        uipanel2                     matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        GridLayout8                  matlab.ui.container.GridLayout
        GridLayout9                  matlab.ui.container.GridLayout
        plotCurveButton              matlab.ui.control.Button
        removePointFromCurveButton   matlab.ui.control.Button
        addPointToCurveButton        matlab.ui.control.Button
        ptsListbox                   matlab.ui.control.ListBox
        GridLayout7                  matlab.ui.container.GridLayout
        interpMethodCombo            matlab.ui.control.DropDown
        text8                        matlab.ui.control.Label
        GridLayout6                  matlab.ui.container.GridLayout
        indepVarCombo                matlab.ui.control.DropDown
        text7                        matlab.ui.control.Label
        GridLayout4                  matlab.ui.container.GridLayout
        text4                        matlab.ui.control.Label
        dragAreaText                 matlab.ui.control.EditField
        text3                        matlab.ui.control.Label
        GridLayout2                  matlab.ui.container.GridLayout
        cancelButton                 matlab.ui.control.Button
        saveCloseButton              matlab.ui.control.Button
        text2                        matlab.ui.control.Label
        cdPtsListboxContextMenu      matlab.ui.container.ContextMenu
        importPointsFromCsvFileMenu  matlab.ui.container.Menu
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, lvdData)
            initStateModel = lvdData.initStateModel;
            aero = initStateModel.aero;

            handles.dragAreaText.String = fullAccNum2Str(aero.area);
            %     handles.dragCoeffText.String = fullAccNum2Str(initStateModel.aero.Cd);

            handles.indepVarCombo.String = AeroIndepVar.getListBoxStr();
            handles.indepVarCombo.Value = AeroIndepVar.getIndForName(aero.CdIndepVar.name);

            handles.interpMethodCombo.String = GriddedInterpolantMethodEnum.getListBoxStr();
            handles.interpMethodCombo.Value = GriddedInterpolantMethodEnum.getIndForName(aero.CdInterpMethod.name);

            handles.ptsListbox.String = aero.CdInterpPts.getListboxStr();
            setRemoveEnableState(app, handles);
        end

        function setRemoveEnableState(app, handles)
            lvdData = getappdata(handles.lvd_EditDragPropertiesGUI,'lvdData');
            initStateModel = lvdData.initStateModel;
            aero = initStateModel.aero;
            numPts = aero.CdInterpPts.getNumPoints();

            if(numPts <= 2)
                handles.removePointFromCurveButton.Enable = 'off';
            else
                handles.removePointFromCurveButton.Enable = 'on';
            end
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            area = str2double(get(handles.dragAreaText,'String'));
            enteredStr = get(handles.dragAreaText,'String');
            numberName = 'Frontal Area';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(area, numberName, lb, ub, isInt, errMsg, enteredStr);
        end


        function saveAndClose(app, handles)
            lvdData = getappdata(handles.lvd_EditDragPropertiesGUI,'lvdData');
            initStateModel = lvdData.initStateModel;
            aero = initStateModel.aero;

            uiArea = str2double(handles.dragAreaText.String);

            aero.area = uiArea;

            strs = handles.indepVarCombo.String();
            str = strs{handles.indepVarCombo.Value};
            aeroIndepVarEnum = AeroIndepVar.getEnumForListboxStr(str);

            strs = handles.interpMethodCombo.String();
            str = strs{handles.interpMethodCombo.Value};
            interpMethodEnum = GriddedInterpolantMethodEnum.getEnumForListboxStr(str);

            aero.CdIndepVar = aeroIndepVarEnum;
            aero.CdInterpMethod = interpMethodEnum;

            gI = aero.CdInterpPts.getGriddedInterpFromPoints(interpMethodEnum, GriddedInterpolantMethodEnum.Nearest);
            aero.CdInterp = gI;

            output2{1} = true;
            output2{2} = aero;
            output2{3} = uiArea;
            app.output.output = output2;
            close(handles.lvd_EditDragPropertiesGUI);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditDragPropertiesGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditDragPropertiesGUI (see VARARGIN)

            % Choose default command line output for lvd_EditDragPropertiesGUI
            handles.output = hObject;

            centerUIFigure(hObject);

            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            app.output = varargin{2};

            populateGUI(app, handles, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditDragPropertiesGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditDragPropertiesGUI);
        end

        % Button pushed function: addPointToCurveButton
        function addPointToCurveButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addPointToCurveButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            prompt = {'Enter the indepenent variable value:', ...
                'Enter the drag coefficient value at the independent variable value:'};
            dlg_title = 'New Drag Coefficient Point';
            num_lines = 1;
            defAns = {'0.0', '0.3'};

            answer = inputdlg(prompt,dlg_title,num_lines,defAns);
            if(not(isempty(answer)))
                lvdData = getappdata(handles.lvd_EditDragPropertiesGUI,'lvdData');
                initStateModel = lvdData.initStateModel;
                aero = initStateModel.aero;

                errMsg = {};

                x = str2double(answer{1});
                enteredStr = answer{1};
                numberName = 'Indepedent Var Value';
                lb = -Inf;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(x, numberName, lb, ub, isInt, errMsg, enteredStr);

                v = str2double(answer{2});
                enteredStr = answer{1};
                numberName = 'Cd Value';
                lb = 0;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(v, numberName, lb, ub, isInt, errMsg, enteredStr);

                [allX, ~] = aero.CdInterpPts.getPointVectors();
                if(ismember(x, allX))
                    errMsg{end+1} = sprintf('A point at entered independent variable (%0.3G) already exists in the table.', x);
                end

                if(isempty(errMsg))
                    newPoint = GriddedInterpolantPoint(x,v);
                    newInd = aero.CdInterpPts.addPoint(newPoint);

                    handles.ptsListbox.String = aero.CdInterpPts.getListboxStr();
                    handles.ptsListbox.Value = newInd;
                else
                    msgbox(errMsg,'Errors were found while adding a Cd value.','error');
                end
            end

            setRemoveEnableState(app, handles);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.output.output = {false,false,false};
            close(handles.lvd_EditDragPropertiesGUI);
        end

        % Value changed function: dragAreaText
        function dragAreaText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to dragAreaText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of dragAreaText as text
            %        str2double(get(hObject,'String')) returns contents of dragAreaText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Menu selected function: importPointsFromCsvFileMenu
        function importPointsFromCsvFileMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to importPointsFromCsvFileMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditDragPropertiesGUI,'lvdData');
            initStateModel = lvdData.initStateModel;
            aero = initStateModel.aero;

            qstring(1) = {'Importing points from CSV will remove all existing points and replace them with the points in the file.  Do you wish to continue?'};
            qstring{2} = '';
            qstring{3} = 'The CSV file should be two columns.  The first column should be that of the independent variable values.  The second column should be the drag coefficient values.  There must be at least two rows.';

            title = 'Import Points from CSV';

            button = questdlg(qstring,title,'Continue','Cancel','Continue');

            if(strcmpi(button,'Continue'))
                DialogTitle = 'Open CSV File';
                [FileName,PathName] = uigetfile({'*.csv','CSV Files (*.csv)'},DialogTitle);

                if(not(FileName == 0))
                    file = fullfile(PathName,FileName);

                    hMsgbox = msgbox('Importing Cd Data from csv file.  Please wait...','modal');

                    try
                        T = readtable(file);
                    catch ME
                        if(isvalid(hMsgbox))
                            close(hMsgbox);
                        end
                        msgbox(sprintf('An error was encountered while reading the CSV file: \n\n%s', ME.message), 'Import Error', 'error');

                        return;
                    end

                    errMsg = {};

                    sizeT = size(T);
                    if(sizeT(1) < 2)
                        errMsg{end+1} = sprintf('The number of rows in the data must be at least two.  Number of rows found: %u', sizeT(1));
                    end

                    if(sizeT(2) < 2)
                        errMsg{end+1} = sprintf('The number of columns in the data must be equal to two.  Number of columns found: %u', sizeT(2));
                    end

                    if(isempty(errMsg))
                        x = table2array(T(:,1));
                        v = table2array(T(:,2));

                        for(i=1:length(x))
                            if(not(isfinite(x(i))) || isnan(x(i)) || ...
                                    not(isfinite(v(i))) || isnan(v(i)))
                                continue;
                            end

                            points(i) = GriddedInterpolantPoint(x(i), abs(v(i)));  %#ok<AGROW>
                        end

                        if(length(points) >= 2)
                            cDInterpPts = aero.CdInterpPts;
                            cDInterpPts.removeAllPoints();

                            for(i=1:length(points))
                                cDInterpPts.addPoint(points(i));
                            end

                            handles.ptsListbox.String = aero.CdInterpPts.getListboxStr();
                            handles.ptsListbox.Value = 1;

                            if(isvalid(hMsgbox))
                                close(hMsgbox);
                            end
                        else
                            if(isvalid(hMsgbox))
                                close(hMsgbox);
                            end
                            msgbox(sprintf('Only %u point(s) was successfully imported.  Check the CSV file format and try again.', length(points)),'Import Error','error');
                        end
                    else
                        if(isvalid(hMsgbox))
                            close(hMsgbox);
                        end
                        msgbox(errMsg,'Errors were found while importing Cd values.','error');

                        return;
                    end
                end
            end
        end

        % Window key press function: lvd_EditDragPropertiesGUI
        function lvd_EditDragPropertiesGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditDragPropertiesGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveCloseButton_Callback(app, evt);
                case 'enter'
                    saveCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditDragPropertiesGUI);
            end
        end

        % Button pushed function: plotCurveButton
        function plotCurveButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to plotCurveButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditDragPropertiesGUI,'lvdData');
            initStateModel = lvdData.initStateModel;
            aero = initStateModel.aero;

            strs = handles.indepVarCombo.String();
            str = strs{handles.indepVarCombo.Value};
            aeroIndepVarEnum = AeroIndepVar.getEnumForListboxStr(str);

            strs = handles.interpMethodCombo.String();
            str = strs{handles.interpMethodCombo.Value};
            interpMethodEnum = GriddedInterpolantMethodEnum.getEnumForListboxStr(str);

            cDInterpPts = aero.CdInterpPts;

            gI = cDInterpPts.getGriddedInterpFromPoints(interpMethodEnum, GriddedInterpolantMethodEnum.Nearest);
            numPts = cDInterpPts.getNumPoints();
            numPtsToPlot = 25*numPts;

            [x, v] = cDInterpPts.getPointVectors();
            xMin = min(x);
            xMax = max(x);
            xWidth = xMax - xMin;

            xPtsToPlot = linspace(xMin - 0.1*xWidth, xMax + 0.1*xWidth, numPtsToPlot);
            vPtsToPlot = gI(xPtsToPlot);
            vPtsToPlot = vPtsToPlot(:);
            vPtsToPlot = max(horzcat(vPtsToPlot, zeros(length(vPtsToPlot), 1)), [], 2);

            hFig = figure();
            hAx = axes(hFig);
            hold(hAx,'on');
            hAx.Color = 'k';

            plot(hAx, xPtsToPlot, vPtsToPlot, '--w');
            plot(hAx, x, v, 'dg', 'MarkerFaceColor','g');

            xlabel(aeroIndepVarEnum.getAxesLabelStr());
            ylabel('Drag Coefficient');

            grid minor;

            ylim(hAx, [-0.05, 1.1*max(vPtsToPlot)]);

            whitebg(hFig);
            hold(hAx,'off');
        end

        % Value changed function: ptsListbox
        function ptsListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to ptsListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns ptsListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from ptsListbox
            %             if(strcmpi(get(handles.lvd_EditDragPropertiesGUI,'SelectionType'),'open'))
            %                 lvdData = getappdata(handles.lvd_EditDragPropertiesGUI,'lvdData');
            %                 initStateModel = lvdData.initStateModel;
            %                 aero = initStateModel.aero;
            %
            %                 indToModify = handles.ptsListbox.Value;
            %                 pointToModify = aero.CdInterpPts.getPointByIndex(indToModify);
            %
            %                 prompt = {'Enter the indepenent variable value:', ...
            %                     'Enter the drag coefficient value at the independent variable value:'};
            %                 dlg_title = 'New Drag Coefficient Point';
            %                 num_lines = 1;
            %                 defAns = {fullAccNum2Str(pointToModify.x), fullAccNum2Str(pointToModify.v)};
            %
            %                 answer = inputdlg(prompt,dlg_title,num_lines,defAns);
            %                 if(not(isempty(answer)))
            %                     errMsg = {};
            %
            %                     x = str2double(answer{1});
            %                     enteredStr = answer{1};
            %                     numberName = 'Indepedent Var Value';
            %                     lb = -Inf;
            %                     ub = Inf;
            %                     isInt = false;
            %                     errMsg = validateNumber(x, numberName, lb, ub, isInt, errMsg, enteredStr);
            %
            %                     v = str2double(answer{2});
            %                     enteredStr = answer{1};
            %                     numberName = 'Cd Value';
            %                     lb = 0;
            %                     ub = Inf;
            %                     isInt = false;
            %                     errMsg = validateNumber(v, numberName, lb, ub, isInt, errMsg, enteredStr);
            %
            %                     [allX, ~] = aero.CdInterpPts.getPointVectors();
            %                     allX(allX == pointToModify.x) = [];
            %                     if(ismember(x, allX))
            %                         errMsg{end+1} = sprintf('A point at entered independent variable (%0.3G) already exists in the table.', x);
            %                     end
            %
            %                     if(isempty(errMsg))
            %                         pointToModify.x = x;
            %                         pointToModify.v = v;
            %                         handles.ptsListbox.String = aero.CdInterpPts.getListboxStr();
            %                     else
            %                         msgbox(errMsg,'Errors were found while adding a Cd value.','error');
            %                     end
            %                 end
            %
            %                 setRemoveEnableState(app, handles);
            %             end
        end

        % Button pushed function: removePointFromCurveButton
        function removePointFromCurveButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removePointFromCurveButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditDragPropertiesGUI,'lvdData');
            initStateModel = lvdData.initStateModel;
            aero = initStateModel.aero;

            oldListBoxValue = handles.ptsListbox.Value;

            indToRemove = handles.ptsListbox.Value;
            pointToRemove = aero.CdInterpPts.getPointByIndex(indToRemove);
            aero.CdInterpPts.removePoint(pointToRemove);

            handles.ptsListbox.String = aero.CdInterpPts.getListboxStr();

            newNumPts = aero.CdInterpPts.getNumPoints();

            if(newNumPts < oldListBoxValue)
                handles.ptsListbox.Value = newNumPts;
            end

            setRemoveEnableState(app, handles);
        end

        % Button pushed function: saveCloseButton
        function saveCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                app.saveAndClose(handles);
            else
                %                 msgbox(errMsg,'Errors were found while editing drag properties.','error');
                uialert(app.lvd_EditDragPropertiesGUI, errMsg, 'Errors were found while editing drag properties.', "Icon","error");
            end
        end

        % Window button down function: lvd_EditDragPropertiesGUI
        function lvd_EditDragPropertiesGUIWindowButtonDown(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            selType = app.lvd_EditDragPropertiesGUI.SelectionType;
            curObj = app.lvd_EditDragPropertiesGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.ptsListbox)
                    lvdData = getappdata(handles.lvd_EditDragPropertiesGUI,'lvdData');
                    initStateModel = lvdData.initStateModel;
                    aero = initStateModel.aero;

                    indToModify = handles.ptsListbox.Value;
                    pointToModify = aero.CdInterpPts.getPointByIndex(indToModify);

                    prompt = {'Enter the indepenent variable value:', ...
                        'Enter the drag coefficient value at the independent variable value:'};
                    dlg_title = 'New Drag Coefficient Point';
                    num_lines = 1;
                    defAns = {fullAccNum2Str(pointToModify.x), fullAccNum2Str(pointToModify.v)};

                    answer = inputdlg(prompt,dlg_title,num_lines,defAns);
                    if(not(isempty(answer)))
                        errMsg = {};

                        x = str2double(answer{1});
                        enteredStr = answer{1};
                        numberName = 'Indepedent Var Value';
                        lb = -Inf;
                        ub = Inf;
                        isInt = false;
                        errMsg = validateNumber(x, numberName, lb, ub, isInt, errMsg, enteredStr);

                        v = str2double(answer{2});
                        enteredStr = answer{1};
                        numberName = 'Cd Value';
                        lb = 0;
                        ub = Inf;
                        isInt = false;
                        errMsg = validateNumber(v, numberName, lb, ub, isInt, errMsg, enteredStr);

                        [allX, ~] = aero.CdInterpPts.getPointVectors();
                        allX(allX == pointToModify.x) = [];
                        if(ismember(x, allX))
                            errMsg{end+1} = sprintf('A point at entered independent variable (%0.3G) already exists in the table.', x);
                        end

                        if(isempty(errMsg))
                            pointToModify.x = x;
                            pointToModify.v = v;
                            handles.ptsListbox.String = aero.CdInterpPts.getListboxStr();
                        else
                            msgbox(errMsg,'Errors were found while adding a Cd value.','error');
                        end
                    end

                    setRemoveEnableState(app, handles);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditDragPropertiesGUI = uifigure('Visible', 'off');
            app.lvd_EditDragPropertiesGUI.Name = 'Edit Drag Properties';
            app.lvd_EditDragPropertiesGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditDragPropertiesGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_EditDragPropertiesGUIWindowButtonDown, true);
            app.lvd_EditDragPropertiesGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditDragPropertiesGUI_WindowKeyPressFcn, true);
            app.lvd_EditDragPropertiesGUI.Tag = 'lvd_EditDragPropertiesGUI';
            app.GridLayout = uigridlayout(app.lvd_EditDragPropertiesGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Drag Properties';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveCloseButton_Callback, true);
            app.saveCloseButton.Tag = 'saveCloseButton';
            app.saveCloseButton.Icon = 'save-file.png';
            app.saveCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.text3 = uilabel(app.GridLayout4);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Frontal Area';
            app.dragAreaText = uieditfield(app.GridLayout4, 'text');
            app.dragAreaText.ValueChangedFcn = createCallbackFcn(app, @dragAreaText_Callback, true);
            app.dragAreaText.Tag = 'dragAreaText';
            app.dragAreaText.Value = '0.0';
            app.text4 = uilabel(app.GridLayout4);
            app.text4.Tag = 'text4';
            app.text4.Text = 'm^2';
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Drag Coefficient';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout5 = uigridlayout(app.uipanel2);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.text7 = uilabel(app.GridLayout6);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Independent Variable';
            app.indepVarCombo = uidropdown(app.GridLayout6);
            app.indepVarCombo.Items = {'Pop-up Menu'};
            app.indepVarCombo.Tag = 'indepVarCombo';
            app.indepVarCombo.Value = 'Pop-up Menu';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.text8 = uilabel(app.GridLayout7);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Interpolation Method';
            app.interpMethodCombo = uidropdown(app.GridLayout7);
            app.interpMethodCombo.Items = {'Pop-up Menu'};
            app.interpMethodCombo.Tag = 'interpMethodCombo';
            app.interpMethodCombo.Value = 'Pop-up Menu';
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.ptsListbox = uilistbox(app.GridLayout8);
            app.ptsListbox.Items = {'Listbox'};
            app.ptsListbox.ValueChangedFcn = createCallbackFcn(app, @ptsListbox_Callback, true);
            app.ptsListbox.Tag = 'ptsListbox';
            app.ptsListbox.Value = 'Listbox';
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.addPointToCurveButton = uibutton(app.GridLayout9, 'push');
            app.addPointToCurveButton.ButtonPushedFcn = createCallbackFcn(app, @addPointToCurveButton_Callback, true);
            app.addPointToCurveButton.Tag = 'addPointToCurveButton';
            app.addPointToCurveButton.Icon = 'add.png';
            app.addPointToCurveButton.Text = 'Add Point To Curve';
            app.removePointFromCurveButton = uibutton(app.GridLayout9, 'push');
            app.removePointFromCurveButton.ButtonPushedFcn = createCallbackFcn(app, @removePointFromCurveButton_Callback, true);
            app.removePointFromCurveButton.Tag = 'removePointFromCurveButton';
            app.removePointFromCurveButton.Icon = 'remove.png';
            app.removePointFromCurveButton.Text = 'Remove Point From Curve';
            app.plotCurveButton = uibutton(app.GridLayout9, 'push');
            app.plotCurveButton.ButtonPushedFcn = createCallbackFcn(app, @plotCurveButton_Callback, true);
            app.plotCurveButton.Tag = 'plotCurveButton';
            app.plotCurveButton.Icon = 'line-chart.png';
            app.plotCurveButton.Text = 'Show Plot of Curve';
            app.cdPtsListboxContextMenu = uicontextmenu(app.lvd_EditDragPropertiesGUI);
            app.cdPtsListboxContextMenu.Tag = 'cdPtsListboxContextMenu';
            app.importPointsFromCsvFileMenu = uimenu(app.cdPtsListboxContextMenu);
            app.importPointsFromCsvFileMenu.MenuSelectedFcn = createCallbackFcn(app, @importPointsFromCsvFileMenu_Callback, true);
            app.importPointsFromCsvFileMenu.Text = 'Import Points from CSV File';
            app.importPointsFromCsvFileMenu.Tag = 'importPointsFromCsvFileMenu';
            % Assign app.cdPtsListboxContextMenu
            app.ptsListbox.ContextMenu = app.cdPtsListboxContextMenu;
            % Show the figure after all components are created
            app.lvd_EditDragPropertiesGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditDragPropertiesGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditDragPropertiesGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditDragPropertiesGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditDragPropertiesGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditDragPropertiesGUI)
        end
    end
end