classdef lvd_EditInitialStateGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditInitialStateGUI         matlab.ui.Figure
        GridLayout10                    matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        uipanel1                        matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        uipanel2                        matlab.ui.container.Panel
        text2                           matlab.ui.control.Label
        orbit6UbText                    matlab.ui.control.EditField
        orbit5UbText                    matlab.ui.control.EditField
        orbit4UbText                    matlab.ui.control.EditField
        orbit3UbText                    matlab.ui.control.EditField
        orbit2UbText                    matlab.ui.control.EditField
        orbit1UbText                    matlab.ui.control.EditField
        utUbText                        matlab.ui.control.EditField
        orbit6LbText                    matlab.ui.control.EditField
        orbit5LbText                    matlab.ui.control.EditField
        orbit4LbText                    matlab.ui.control.EditField
        orbit3LbText                    matlab.ui.control.EditField
        orbit2LbText                    matlab.ui.control.EditField
        orbit1LbText                    matlab.ui.control.EditField
        utLbText                        matlab.ui.control.EditField
        orbit6Text                      matlab.ui.control.EditField
        orbit5Text                      matlab.ui.control.EditField
        orbit4Text                      matlab.ui.control.EditField
        orbit3Text                      matlab.ui.control.EditField
        orbit2Text                      matlab.ui.control.EditField
        orbit1Text                      matlab.ui.control.EditField
        utText                          matlab.ui.control.EditField
        optOrbit6Checkbox               matlab.ui.control.CheckBox
        optOrbit5Checkbox               matlab.ui.control.CheckBox
        optOrbit4Checkbox               matlab.ui.control.CheckBox
        optOrbit3Checkbox               matlab.ui.control.CheckBox
        optOrbit2Checkbox               matlab.ui.control.CheckBox
        uipanel4                        matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        editLvAndStageStatesButton      matlab.ui.control.Button
        uipanel5                        matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        editLiftPropsButton             matlab.ui.control.Button
        editDragPropsButton             matlab.ui.control.Button
        uipanel8                        matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        edit3BodyGravPropertiesButton   matlab.ui.control.Button
        uipanel7                        matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        editThrottleButton              matlab.ui.control.Button
        editSteeringButton              matlab.ui.control.Button
        uipanel3                        matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        refFrameTypeCombo               matlab.ui.control.DropDown
        setFrameOptionsButton           matlab.ui.control.Button
        elementSetCombo                 matlab.ui.control.DropDown
        text24                          matlab.ui.control.Label
        text17                          matlab.ui.control.Label
        GridLayout4                     matlab.ui.container.GridLayout
        cancelButton                    matlab.ui.control.Button
        saveAndCloseButton              matlab.ui.control.Button
        optOrbit1Checkbox               matlab.ui.control.CheckBox
        optUtCheckbox                   matlab.ui.control.CheckBox
        orbit6UnitLabel                 matlab.ui.control.Label
        orbit5UnitLabel                 matlab.ui.control.Label
        orbit4UnitLabel                 matlab.ui.control.Label
        orbit3UnitLabel                 matlab.ui.control.Label
        orbit2UnitLabel                 matlab.ui.control.Label
        orbit1UnitLabel                 matlab.ui.control.Label
        utUnitLabel                     matlab.ui.control.Label
        orbit6Label                     matlab.ui.control.Label
        orbit5Label                     matlab.ui.control.Label
        orbit4Label                     matlab.ui.control.Label
        orbit3Label                     matlab.ui.control.Label
        orbit2Label                     matlab.ui.control.Label
        orbit1Label                     matlab.ui.control.Label
        text3                           matlab.ui.control.Label
        epochContextMenu                matlab.ui.container.ContextMenu
        enterUTAsDateTimeContextMenu    matlab.ui.container.Menu
        orbitPanelContextMenu           matlab.ui.container.ContextMenu
        getOrbitFromSFSFileContextMenu  matlab.ui.container.Menu
        getOrbitFromKSPTOTConnectContextMenu  matlab.ui.container.Menu
        getOrbitFromKSPActiveVesselMenu  matlab.ui.container.Menu
        copyOrbitToClipboardMenu        matlab.ui.container.Menu
        pasteOrbitFromClipboardMenu     matlab.ui.container.Menu
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function dragCoeffText_Callback(app, hObject, eventdata, handles)
            % hObject    handle to dragCoeffText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of dragCoeffText as text
            %        str2double(get(hObject,'String')) returns contents of dragCoeffText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        function dragCoeffText_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to dragCoeffText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function frontalAreaText_Callback(app, hObject, eventdata, handles)
            % hObject    handle to frontalAreaText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of frontalAreaText as text
            %        str2double(get(hObject,'String')) returns contents of frontalAreaText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        function frontalAreaText_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to frontalAreaText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function [orbitModel, elemSetEnum] = getCurrentElemSetFromExistingValues(app, handles)
            contents = cellstr(get(handles.elementSetCombo,'String'));
            selElemSet = contents{get(handles.elementSetCombo,'Value')};
            elemSetEnum = ElementSetEnum.getEnumForListboxStr(selElemSet);

            time = str2double(handles.utText.String);

            orbit1Elem = str2double(handles.orbit1Text.String);
            orbit2Elem = str2double(handles.orbit2Text.String);
            orbit3Elem = str2double(handles.orbit3Text.String);
            orbit4Elem = str2double(handles.orbit4Text.String);
            orbit5Elem = str2double(handles.orbit5Text.String);
            orbit6Elem = str2double(handles.orbit6Text.String);

            curElemSet = getappdata(handles.lvd_EditInitialStateGUI,'curElemSet');
            frame = curElemSet.frame;

            switch elemSetEnum
                case ElementSetEnum.CartesianElements
                    orbitModel = CartesianElementSet(time, [orbit1Elem; orbit2Elem; orbit3Elem], [orbit4Elem; orbit5Elem; orbit6Elem], frame);

                case ElementSetEnum.KeplerianElements
                    orbitModel = KeplerianElementSet(time, orbit1Elem, orbit2Elem, deg2rad(orbit3Elem), deg2rad(orbit4Elem), deg2rad(orbit5Elem), deg2rad(orbit6Elem), frame);

                case ElementSetEnum.GeographicElements
                    orbitModel = GeographicElementSet(time, deg2rad(orbit1Elem), deg2rad(orbit2Elem), orbit3Elem, deg2rad(orbit4Elem), deg2rad(orbit5Elem), orbit6Elem, frame);

                case ElementSetEnum.UniversalElements
                    orbitModel = UniversalElementSet(time, orbit1Elem, orbit2Elem, deg2rad(orbit3Elem), deg2rad(orbit4Elem), deg2rad(orbit5Elem), orbit6Elem, frame);

                otherwise
                    error('Unknown element set type: %s', class(elemSetEnum));
            end
        end

        function bodyInfo = getSelectedBodyInfo(app, handles)
            curElemSet = getappdata(handles.lvd_EditInitialStateGUI,'curElemSet');
            bodyInfo = curElemSet.frame.getOriginBody();
        end

        function populateGUI(app, handles, lvdData)
            initStateModel = lvdData.initStateModel;
            setappdata(handles.lvd_EditInitialStateGUI,'curElemSet',initStateModel.orbitModel);

            handles.refFrameTypeCombo.String = ReferenceFrameEnum.getListBoxStr();
            handles.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(initStateModel.orbitModel.frame.typeEnum.name);
            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', initStateModel.orbitModel.frame.getNameStr());

            handles.elementSetCombo.String = ElementSetEnum.getListBoxStr();
            value = ElementSetEnum.getIndForName(initStateModel.orbitModel.typeEnum.name);
            handles.elementSetCombo.Value = value;

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            elementSetCombo_Callback(app, evt);

            handles.utText.String = fullAccNum2Str(initStateModel.time);

            elemVector = initStateModel.orbitModel.getElementVector();
            handles.orbit1Text.String = fullAccNum2Str(elemVector(1));
            handles.orbit2Text.String = fullAccNum2Str(elemVector(2));
            handles.orbit3Text.String = fullAccNum2Str(elemVector(3));
            handles.orbit4Text.String = fullAccNum2Str(elemVector(4));
            handles.orbit5Text.String = fullAccNum2Str(elemVector(5));
            handles.orbit6Text.String = fullAccNum2Str(elemVector(6));

            optVar = initStateModel.getExistingOptVar();
            if(isempty(optVar))
                optVar = initStateModel.getNewOptVar();
            end
            useTf = optVar.getUseTfForVariable();

            handles.optUtCheckbox.Value = double(useTf(1));
            handles.optOrbit1Checkbox.Value = double(useTf(2));
            handles.optOrbit2Checkbox.Value = double(useTf(3));
            handles.optOrbit3Checkbox.Value = double(useTf(4));
            handles.optOrbit4Checkbox.Value = double(useTf(5));
            handles.optOrbit5Checkbox.Value = double(useTf(6));
            handles.optOrbit6Checkbox.Value = double(useTf(7));

            optVar.setUseTfForVariable(true(size(useTf)));
            [lb, ub] = optVar.getBndsForVariable();
            optVar.setUseTfForVariable(useTf);

            % convert units on bounds
            contents = cellstr(get(handles.elementSetCombo,'String'));
            selElemSet = contents{get(handles.elementSetCombo,'Value')};
            elemSetEnum = ElementSetEnum.getEnumForListboxStr(selElemSet);

            switch elemSetEnum
                case ElementSetEnum.CartesianElements
                    %no unit conversion needed

                case ElementSetEnum.KeplerianElements
                    lb(3:end) = rad2deg(lb(3:end));
                    ub(3:end) = rad2deg(ub(3:end));

                case ElementSetEnum.GeographicElements
                    lb(2:3) = rad2deg(lb(2:3));
                    lb(5:6) = rad2deg(lb(5:6));

                    ub(2:3) = rad2deg(ub(2:3));
                    ub(5:6) = rad2deg(ub(5:6));

                case ElementSetEnum.UniversalElements
                    lb(4:6) = rad2deg(lb(4:6));
                    ub(4:6) = rad2deg(ub(4:6));

                otherwise
                    error('Unknown element set type: %s', class(elemSetEnum));
            end

            handles.utLbText.String     = fullAccNum2Str(lb(1));
            handles.orbit1LbText.String = fullAccNum2Str(lb(2));
            handles.orbit2LbText.String = fullAccNum2Str(lb(3));
            handles.orbit3LbText.String = fullAccNum2Str(lb(4));
            handles.orbit4LbText.String = fullAccNum2Str(lb(5));
            handles.orbit5LbText.String = fullAccNum2Str(lb(6));
            handles.orbit6LbText.String = fullAccNum2Str(lb(7));

            handles.utUbText.String     = fullAccNum2Str(ub(1));
            handles.orbit1UbText.String = fullAccNum2Str(ub(2));
            handles.orbit2UbText.String = fullAccNum2Str(ub(3));
            handles.orbit3UbText.String = fullAccNum2Str(ub(4));
            handles.orbit4UbText.String = fullAccNum2Str(ub(5));
            handles.orbit5UbText.String = fullAccNum2Str(ub(6));
            handles.orbit6UbText.String = fullAccNum2Str(ub(7));

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            optUtCheckbox_Callback(app, evt);
            optOrbit1Checkbox_Callback(app, evt);
            optOrbit2Checkbox_Callback(app, evt);
            optOrbit3Checkbox_Callback(app, evt);
            optOrbit4Checkbox_Callback(app, evt);
            optOrbit5Checkbox_Callback(app, evt);
            optOrbit6Checkbox_Callback(app, evt);
        end

        function updateStateDueToFrameChange(app, handles, newFrame)
            lvdData = getappdata(handles.lvd_EditInitialStateGUI,'lvdData');
            celBodyData = lvdData.celBodyData;

            curElemSet = getappdata(handles.lvd_EditInitialStateGUI,'curElemSet');

            contents = cellstr(get(handles.refFrameTypeCombo,'String'));
            selFrameType = contents{get(handles.refFrameTypeCombo,'Value')};
            refFrameEnum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);

            if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
                refFrameEnum = newFrame.typeEnum;
                handles.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
            end

            switch refFrameEnum
                case ReferenceFrameEnum.BodyCenteredInertial
                    if(isempty(newFrame))
                        bodyInfo = getSelectedBodyInfo(app, handles);
                    else
                        bodyInfo = newFrame.getOriginBody();
                    end

                    newFrame = bodyInfo.getBodyCenteredInertialFrame();

                case ReferenceFrameEnum.BodyFixedRotating
                    if(isempty(newFrame))
                        bodyInfo = getSelectedBodyInfo(app, handles);
                    else
                        bodyInfo = newFrame.getOriginBody();
                    end

                    newFrame = bodyInfo.getBodyFixedFrame();

                case ReferenceFrameEnum.TwoBodyRotating
                    if(isempty(newFrame))
                        bodyInfo = getSelectedBodyInfo(app, handles);
                        if(not(isempty(bodyInfo.childrenBodyInfo)))
                            primaryBody = bodyInfo;
                            secondaryBody = bodyInfo.childrenBodyInfo(1);
                        else
                            primaryBody = bodyInfo.getParBodyInfo();
                            secondaryBody = bodyInfo;
                        end

                        originPt = TwoBodyRotatingFrameOriginEnum.Primary;

                        newFrame = TwoBodyRotatingFrame(primaryBody, secondaryBody, originPt, celBodyData);
                    else
                        newFrame = TwoBodyRotatingFrame(newFrame.primaryBodyInfo, newFrame.secondaryBodyInfo, newFrame.originPt, celBodyData);
                    end

                case ReferenceFrameEnum.UserDefined
                    if(isempty(newFrame))
                        numFrames = lvdData.geometry.refFrames.getNumRefFrames();
                        if(numFrames >= 1)
                            geometricFrame = AbstractGeometricRefFrame.empty(1,0);
                            for(i=1:numFrames)
                                frame = lvdData.geometry.refFrames.getRefFrameAtInd(i);
                                if(frame.isVehDependent() == false)
                                    geometricFrame = frame;
                                    break;
                                end
                            end

                            if(not(isempty(geometricFrame)))
                                newFrame = UserDefinedGeometricFrame(geometricFrame, lvdData);
                            else
                                bodyInfo = getSelectedBodyInfo(app, handles);
                                newFrame = bodyInfo.getBodyCenteredInertialFrame();

                                warndlg('There are no geometric frames available which are not dependent on vehicle properties.  A body-centered inertial frame will be selected instead.');
                            end
                        else
                            bodyInfo = getSelectedBodyInfo(app, handles);
                            newFrame = bodyInfo.getBodyCenteredInertialFrame();

                            warndlg('There are no geometric frames available which are not dependent on vehicle properties.  A body-centered inertial frame will be selected instead.');
                        end

                    else
                        newFrame = UserDefinedGeometricFrame(newFrame.geometricFrame, lvdData);
                    end

                otherwise
                    error('Unknown reference frame type: %s', string(refFrameEnum));
            end

            if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
                refFrameEnum = newFrame.typeEnum;
                handles.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
            end

            try
                newElemSet = curElemSet.convertToFrame(newFrame);

                elemVect = newElemSet.getElementVector();

                if(any(isnan(elemVect)) || any(not(isfinite(elemVect))))
                    error('State conversion resulted in NaN');
                end

                handles.orbit1Text.String = fullAccNum2Str(elemVect(1));
                handles.orbit2Text.String = fullAccNum2Str(elemVect(2));
                handles.orbit3Text.String = fullAccNum2Str(elemVect(3));
                handles.orbit4Text.String = fullAccNum2Str(elemVect(4));
                handles.orbit5Text.String = fullAccNum2Str(elemVect(5));
                handles.orbit6Text.String = fullAccNum2Str(elemVect(6));

                setappdata(handles.lvd_EditInitialStateGUI,'curElemSet', newElemSet);
            catch ME
                updateValuesInState(app, handles);
%                 msgbox('Error converting state to new frame.','Conversion Error','error');
                uialert(app.lvd_EditInitialStateGUI, 'Error converting state to new frame.', 'Conversion Error', "Icon","error");
            end

            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', newFrame.getNameStr());
        end

        function updateValuesInState(app, handles)
            curElemSet = getappdata(handles.lvd_EditInitialStateGUI,'curElemSet');
            newElemSet = curElemSet;

            time = str2double(handles.utText.String);
            orbit1Elem = str2double(handles.orbit1Text.String);
            orbit2Elem = str2double(handles.orbit2Text.String);
            orbit3Elem = str2double(handles.orbit3Text.String);
            orbit4Elem = str2double(handles.orbit4Text.String);
            orbit5Elem = str2double(handles.orbit5Text.String);
            orbit6Elem = str2double(handles.orbit6Text.String);

            frame = newElemSet.frame;
            switch newElemSet.typeEnum
                case ElementSetEnum.CartesianElements
                    newElemSet = CartesianElementSet(time, [orbit1Elem; orbit2Elem; orbit3Elem], [orbit4Elem; orbit5Elem; orbit6Elem], frame);

                case ElementSetEnum.KeplerianElements
                    newElemSet = KeplerianElementSet(time, orbit1Elem, orbit2Elem, deg2rad(orbit3Elem), deg2rad(orbit4Elem), deg2rad(orbit5Elem), deg2rad(orbit6Elem), frame);

                case ElementSetEnum.GeographicElements
                    newElemSet = GeographicElementSet(time, deg2rad(orbit1Elem), deg2rad(orbit2Elem), orbit3Elem, deg2rad(orbit4Elem), deg2rad(orbit5Elem), orbit6Elem, frame);

                case ElementSetEnum.UniversalElements
                    newElemSet = UniversalElementSet(time, orbit1Elem, orbit2Elem, deg2rad(orbit3Elem), deg2rad(orbit4Elem), deg2rad(orbit5Elem), orbit6Elem, frame);

                otherwise
                    error('Unknown element set type: %s', class(elemSetEnum));
            end

            setappdata(handles.lvd_EditInitialStateGUI,'curElemSet', newElemSet);
        end

        function errMsg = validateInputs(app, handles)
            lvdData = getappdata(handles.lvd_EditInitialStateGUI,'lvdData');
            celBodyData = lvdData.celBodyData;

            errMsg = {};
            bodyInfo = getSelectedBodyInfo(app, handles);

            ut = str2double(get(handles.utText,'String'));
            enteredStr = get(handles.utText,'String');
            numberName = 'Epoch (UT)';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(ut, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(handles.optUtCheckbox.Value)
                utLb = str2double(get(handles.utLbText,'String'));
                enteredStr = get(handles.utLbText,'String');
                numberName = 'Epoch (UT) (Lower Bound)';
                lb = 0;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(utLb, numberName, lb, ub, isInt, errMsg, enteredStr);

                utUb = str2double(get(handles.utUbText,'String'));
                enteredStr = get(handles.utUbText,'String');
                numberName = 'Epoch (UT) (Upper Bound)';
                lb = 0;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(utUb, numberName, lb, ub, isInt, errMsg, enteredStr);

                if(utLb < utUb)
                    ut = str2double(get(handles.utText,'String'));
                    enteredStr = get(handles.utText,'String');
                    numberName = 'Epoch (UT)';
                    isInt = false;
                    errMsg = validateNumber(ut, numberName, utLb, utUb, isInt, errMsg, enteredStr);
                else
                    errMsg{end+1} = sprintf('%s lower bound must be less than the upper bound.', 'Epoch (UT)');
                end
            end

            contents = cellstr(get(handles.elementSetCombo,'String'));
            selElemSet = contents{get(handles.elementSetCombo,'Value')};
            elemSetEnum = ElementSetEnum.getEnumForListboxStr(selElemSet);

            checkElementValues = ones(1,6);
            checkElementBnds = [handles.optOrbit1Checkbox.Value, ...
                handles.optOrbit2Checkbox.Value, ...
                handles.optOrbit3Checkbox.Value, ...
                handles.optOrbit4Checkbox.Value, ...
                handles.optOrbit5Checkbox.Value, ...
                handles.optOrbit6Checkbox.Value];

            switch elemSetEnum
                case ElementSetEnum.CartesianElements
                    errMsg = CartesianElementSet.validateInputOrbit(errMsg, handles.orbit1Text, handles.orbit2Text, handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, handles.orbit6Text, bodyInfo, '', checkElementValues);
                    errMsg = CartesianElementSet.validateInputOrbit(errMsg, handles.orbit1LbText, handles.orbit2LbText, handles.orbit3LbText, handles.orbit4LbText, handles.orbit5LbText, handles.orbit6LbText, bodyInfo, 'Lower', checkElementBnds);
                    errMsg = CartesianElementSet.validateInputOrbit(errMsg, handles.orbit1UbText, handles.orbit2UbText, handles.orbit3UbText, handles.orbit4UbText, handles.orbit5UbText, handles.orbit6UbText, bodyInfo, 'Upper', checkElementBnds);

                case ElementSetEnum.KeplerianElements
                    errMsg = KeplerianElementSet.validateInputOrbit(errMsg, handles.orbit1Text, handles.orbit2Text, handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, handles.orbit6Text, bodyInfo, '', checkElementValues);
                    errMsg = KeplerianElementSet.validateInputOrbit(errMsg, handles.orbit1LbText, handles.orbit2LbText, handles.orbit3LbText, handles.orbit4LbText, handles.orbit5LbText, handles.orbit6LbText, bodyInfo, 'Lower', checkElementBnds);
                    errMsg = KeplerianElementSet.validateInputOrbit(errMsg, handles.orbit1UbText, handles.orbit2UbText, handles.orbit3UbText, handles.orbit4UbText, handles.orbit5UbText, handles.orbit6UbText, bodyInfo, 'Upper', checkElementBnds);

                case ElementSetEnum.GeographicElements
                    errMsg = GeographicElementSet.validateInputOrbit(errMsg, handles.orbit1Text, handles.orbit2Text, handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, handles.orbit6Text, bodyInfo, '', checkElementValues);
                    errMsg = GeographicElementSet.validateInputOrbit(errMsg, handles.orbit1LbText, handles.orbit2LbText, handles.orbit3LbText, handles.orbit4LbText, handles.orbit5LbText, handles.orbit6LbText, bodyInfo, 'Lower', checkElementBnds);
                    errMsg = GeographicElementSet.validateInputOrbit(errMsg, handles.orbit1UbText, handles.orbit2UbText, handles.orbit3UbText, handles.orbit4UbText, handles.orbit5UbText, handles.orbit6UbText, bodyInfo, 'Upper', checkElementBnds);

                case ElementSetEnum.UniversalElements
                    errMsg = UniversalElementSet.validateInputOrbit(errMsg, handles.orbit1Text, handles.orbit2Text, handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, handles.orbit6Text, bodyInfo, '', checkElementValues);
                    errMsg = UniversalElementSet.validateInputOrbit(errMsg, handles.orbit1LbText, handles.orbit2LbText, handles.orbit3LbText, handles.orbit4LbText, handles.orbit5LbText, handles.orbit6LbText, bodyInfo, 'Lower', checkElementBnds);
                    errMsg = UniversalElementSet.validateInputOrbit(errMsg, handles.orbit1UbText, handles.orbit2UbText, handles.orbit3UbText, handles.orbit4UbText, handles.orbit5UbText, handles.orbit6UbText, bodyInfo, 'Upper', checkElementBnds);

                otherwise
                    error('Unknown element set type: %s', class(elemSetEnum));
            end

            hVal = [handles.orbit1Text, handles.orbit2Text, handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, handles.orbit6Text];
            hLb = [handles.orbit1LbText, handles.orbit2LbText, handles.orbit3LbText, handles.orbit4LbText, handles.orbit5LbText, handles.orbit6LbText];
            hUb = [handles.orbit1UbText, handles.orbit2UbText, handles.orbit3UbText, handles.orbit4UbText, handles.orbit5UbText, handles.orbit6UbText];

            elemNames = elemSetEnum.elemNames;

            for(i=1:length(hVal))
                if(checkElementBnds(i) == 1)
                    lb = str2double(get(hLb(i),'String'));
                    ub = str2double(get(hUb(i),'String'));

                    if(lb <= ub)
                        value = str2double(get(hVal(i),'String'));
                        enteredStr = get(hVal(i),'String');
                        numberName = [elemNames{i}];
                        isInt = false;
                        errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);
                    else
                        errMsg{end+1} = sprintf('%s lower bound must be less than the upper bound.', elemNames{i}); %#ok<AGROW>
                    end
                end
            end
        end


        function saveAndCloseFunction(app, handles)
            lvdData = getappdata(handles.lvd_EditInitialStateGUI,'lvdData');
            initStateModel = lvdData.initStateModel;

            %         contents = cellstr(get(handles.elementSetCombo,'String'));
            %         selElemSet = contents{get(handles.elementSetCombo,'Value')};
            %         elemSetEnum = ElementSetEnum.getEnumForListboxStr(selElemSet);
            %
            %         time = str2double(handles.utText.String);
            %
            %         orbit1Elem = str2double(handles.orbit1Text.String);
            %         orbit2Elem = str2double(handles.orbit2Text.String);
            %         orbit3Elem = str2double(handles.orbit3Text.String);
            %         orbit4Elem = str2double(handles.orbit4Text.String);
            %         orbit5Elem = str2double(handles.orbit5Text.String);
            %         orbit6Elem = str2double(handles.orbit6Text.String);
            %
            %         curElemSet = getappdata(handles.lvd_EditInitialStateGUI,'curElemSet');
            %         frame = curElemSet.frame;
            %
            %         switch elemSetEnum
            %             case ElementSetEnum.CartesianElements
            %                 orbitModel = CartesianElementSet(time, [orbit1Elem; orbit2Elem; orbit3Elem], [orbit4Elem; orbit5Elem; orbit6Elem], frame);
            %
            %             case ElementSetEnum.KeplerianElements
            %                 orbitModel = KeplerianElementSet(time, orbit1Elem, orbit2Elem, deg2rad(orbit3Elem), deg2rad(orbit4Elem), deg2rad(orbit5Elem), deg2rad(orbit6Elem), frame);
            %
            %             case ElementSetEnum.GeographicElements
            %                 orbitModel = GeographicElementSet(time, deg2rad(orbit1Elem), deg2rad(orbit2Elem), orbit3Elem, deg2rad(orbit4Elem), deg2rad(orbit5Elem), orbit6Elem, frame);
            %
            %             case ElementSetEnum.UniversalElements
            %                 orbitModel = UniversalElementSet(time, orbit1Elem, orbit2Elem, deg2rad(orbit3Elem), deg2rad(orbit4Elem), deg2rad(orbit5Elem), orbit6Elem, frame);
            %
            %             otherwise
            %                 error('Unknown element set type: %s', class(elemSetEnum));
            %         end

            [orbitModel, elemSetEnum] = getCurrentElemSetFromExistingValues(app, handles);

            initStateModel.orbitModel = orbitModel;

            %Vars
            optVar = initStateModel.getExistingOptVar();

            if(not(isempty(optVar)))
                lvdData.optimizer.vars.removeVariable(optVar);
            end
            optVar = initStateModel.getNewOptVar();

            optUt         = logical(handles.optUtCheckbox.Value);
            optOrbit1Elem = logical(handles.optOrbit1Checkbox.Value);
            optOrbit2Elem = logical(handles.optOrbit2Checkbox.Value);
            optOrbit3Elem = logical(handles.optOrbit3Checkbox.Value);
            optOrbit4Elem = logical(handles.optOrbit4Checkbox.Value);
            optOrbit5Elem = logical(handles.optOrbit5Checkbox.Value);
            optOrbit6Elem = logical(handles.optOrbit6Checkbox.Value);

            useTf = [optUt optOrbit1Elem optOrbit2Elem optOrbit3Elem optOrbit4Elem optOrbit5Elem optOrbit6Elem];
            optVar.setUseTfForVariable(useTf);
            lvdData.optimizer.vars.addVariable(optVar);

            utLb = str2double(handles.utLbText.String);
            orbit1ElemLb = str2double(handles.orbit1LbText.String);
            orbit2ElemLb = str2double(handles.orbit2LbText.String);
            orbit3ElemLb = str2double(handles.orbit3LbText.String);
            orbit4ElemLb = str2double(handles.orbit4LbText.String);
            orbit5ElemLb = str2double(handles.orbit5LbText.String);
            orbit6ElemLb = str2double(handles.orbit6LbText.String);

            utUb = str2double(handles.utUbText.String);
            orbit1ElemUb = str2double(handles.orbit1UbText.String);
            orbit2ElemUb = str2double(handles.orbit2UbText.String);
            orbit3ElemUb = str2double(handles.orbit3UbText.String);
            orbit4ElemUb = str2double(handles.orbit4UbText.String);
            orbit5ElemUb = str2double(handles.orbit5UbText.String);
            orbit6ElemUb = str2double(handles.orbit6UbText.String);

            lb = [utLb orbit1ElemLb orbit2ElemLb orbit3ElemLb orbit4ElemLb orbit5ElemLb orbit6ElemLb];
            ub = [utUb orbit1ElemUb orbit2ElemUb orbit3ElemUb orbit4ElemUb orbit5ElemUb orbit6ElemUb];

            for(i=1:length(lb)) %#ok<*NO4LP>
                if(lb(i) > ub(i))
                    temp1 = lb(i);
                    temp2 = ub(i);

                    lb(i) = temp2;
                    ub(i) = temp1;
                end
            end

            switch elemSetEnum
                case ElementSetEnum.CartesianElements
                    %no unit conversion needed

                case ElementSetEnum.KeplerianElements
                    lb(3:end) = deg2rad(lb(3:end));
                    ub(3:end) = deg2rad(ub(3:end));

                case ElementSetEnum.GeographicElements
                    lb(2:3) = deg2rad(lb(2:3));
                    lb(5:6) = deg2rad(lb(5:6));

                    ub(2:3) = deg2rad(ub(2:3));
                    ub(5:6) = deg2rad(ub(5:6));

                case ElementSetEnum.UniversalElements
                    lb(4:6) = deg2rad(lb(4:6));
                    ub(4:6) = deg2rad(ub(4:6));

                otherwise
                    error('Unknown element set type: %s', string(elemSetEnum));
            end

            optVar.setBndsForVariable(lb, ub);

            app.output.output = {true};
            close(handles.lvd_EditInitialStateGUI);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditInitialStateGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditInitialStateGUI (see VARARGIN)

            % Choose default command line output for lvd_EditInitialStateGUI
            handles.output = hObject;

            centerUIFigure(hObject);

            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            handles.ksptotMainGUI = varargin{2};

            app.output = varargin{3};

            populateGUI(app, handles, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditInitialStateGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditInitialStateGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditInitialStateGUI);
        end

        % Menu selected function: copyOrbitToClipboardMenu
        function copyOrbitToClipboardMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to copyOrbitToClipboardMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            bodyInfo = getSelectedBodyInfo(app, handles);

            o1V = handles.orbit1Text.String;
            o2V = handles.orbit2Text.String;
            o3V = handles.orbit3Text.String;
            o4V = handles.orbit4Text.String;
            o5V = handles.orbit5Text.String;
            o6V = handles.orbit6Text.String;

            contents = cellstr(get(handles.refFrameTypeCombo,'String'));
            selFrameType = contents{get(handles.refFrameTypeCombo,'Value')};
            enum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);
            framEnumOrig = enum;
            if(not(enum == ReferenceFrameEnum.BodyCenteredInertial))
                enum = ReferenceFrameEnum.BodyCenteredInertial;
                ind = ReferenceFrameEnum.getIndForName(enum.name);
                handles.refFrameTypeCombo.Value = ind;
                refFrameTypeCombo_Callback(app, evt);
            end

            contents = cellstr(get(handles.elementSetCombo,'String'));
            selOrbitType = contents{get(handles.elementSetCombo,'Value')};
            [~,enum] = ElementSetEnum.getIndForName(selOrbitType);
            elemSetEnumOrig = enum;
            if(not(enum == ElementSetEnum.KeplerianElements))
                enum = ElementSetEnum.KeplerianElements;
                ind = ElementSetEnum.getIndForName(enum.name);
                handles.elementSetCombo.Value = ind;
                elementSetCombo_Callback(app, evt);
            end

            copyOrbitToClipboardFromText(handles.utText, handles.orbit1Text, handles.orbit2Text, ...
                handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, ...
                handles.orbit6Text, true, bodyInfo.id);

            ind = ElementSetEnum.getIndForName(elemSetEnumOrig.name);
            handles.elementSetCombo.Value = ind;
            elementSetCombo_Callback(app, evt);

            ind = ReferenceFrameEnum.getIndForName(framEnumOrig.name);
            handles.refFrameTypeCombo.Value = ind;
            refFrameTypeCombo_Callback(app, evt);

            handles.orbit1Text.String = o1V;
            handles.orbit2Text.String = o2V;
            handles.orbit3Text.String = o3V;
            handles.orbit4Text.String = o4V;
            handles.orbit5Text.String = o5V;
            handles.orbit6Text.String = o6V;
        end

        % Button pushed function: edit3BodyGravPropertiesButton
        function edit3BodyGravPropertiesButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit3BodyGravPropertiesButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditInitialStateGUI,'lvdData');
            celBodyData = lvdData.celBodyData;

            [bodiesStr, sortedBodyInfo] = ma_getSortedBodyNames(celBodyData);
            promptStr = 'Select the bodies you wish to use as third body gravity sources.';

            sortedBodyInfoArr = KSPTOT_BodyInfo.empty(1,0);
            for(i=1:length(sortedBodyInfo))
                sortedBodyInfoArr(end+1) = sortedBodyInfo{i}; %#ok<AGROW>
            end

            curSelBodies = lvdData.initStateModel.thirdBodyGravity.bodies;
            [~,inds,~] = intersect(sortedBodyInfoArr,curSelBodies);

            [Selection,ok] = listdlgARH('ListString', bodiesStr,...
                'InitialValue', inds, ...
                'Name', '3rd Body Gravity Sources', ...
                'PromptString', promptStr, ...
                'ListSize', [350 300]);

            if(ok == 1)
                lvdData.initStateModel.thirdBodyGravity.bodies = sortedBodyInfoArr(Selection);
            end
        end

        % Button pushed function: editDragPropsButton
        function editDragPropsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editDragPropsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditInitialStateGUI,'lvdData');
            
            output2 = AppDesignerGUIOutput({false,false,false});
            lvd_EditDragPropertiesGUI_App(lvdData, output2);
        end

        % Button pushed function: editLiftPropsButton
        function editLiftPropsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editLiftPropsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditInitialStateGUI,'lvdData');
%             lvd_EditLiftPropertiesGUI(lvdData);

            output2 = AppDesignerGUIOutput({false,false,false,false,false});
            lvd_EditLiftPropertiesGUI_App(lvdData, output2);
        end

        % Button pushed function: editLvAndStageStatesButton
        function editLvAndStageStatesButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editLvAndStageStatesButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditInitialStateGUI,'lvdData');
%             lvd_EditLvAndStagesStatesGUI(lvdData);
            
            output2 = AppDesignerGUIOutput({false});
            lvd_EditLvAndStagesStatesGUI_App(lvdData, output2);
        end

        % Button pushed function: editSteeringButton
        function editSteeringButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editSteeringButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditInitialStateGUI,'lvdData');
            initStateModel = lvdData.initStateModel;
            lv = lvdData.launchVehicle;

            steeringModel = promptForSteeringModelType(initStateModel.steeringModel.getSteeringModelTypeEnum());

            if(initStateModel.steeringModel.getSteeringModelTypeEnum() == steeringModel.getSteeringModelTypeEnum())
                [addActionTf,steeringModel] = initStateModel.steeringModel.openEditSteeringModelUI(lv, false);
            else
                [addActionTf,steeringModel] = steeringModel.openEditSteeringModelUI(lv, false);
            end

            if(addActionTf)
                initStateModel.steeringModel = steeringModel;
            end
        end

        % Button pushed function: editThrottleButton
        function editThrottleButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editThrottleButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditInitialStateGUI,'lvdData');
            initStateModel = lvdData.initStateModel;
            lv = lvdData.launchVehicle;

            throttleModel = promptForThrottleModelType(initStateModel.throttleModel.getThrottleModelTypeEnum());

            if(initStateModel.throttleModel.getThrottleModelTypeEnum() == throttleModel.getThrottleModelTypeEnum())
                [addActionTf,throttleModel] = initStateModel.throttleModel.openEditThrottleModelUI(lv, false);
            else
                [addActionTf,throttleModel] = throttleModel.openEditThrottleModelUI(lv, false);
            end

            if(addActionTf)
                initStateModel.throttleModel = throttleModel;
            end
        end

        % Value changed function: elementSetCombo
        function elementSetCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.elementSetCombo;
            
            % hObject    handle to elementSetCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns elementSetCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from elementSetCombo
            % 	lvdData = getappdata(handles.lvd_EditInitialStateGUI,'lvdData');

            contents = cellstr(get(hObject,'String'));
            sel = contents{get(hObject,'Value')};
            [~, elemSetEnum] = ElementSetEnum.getIndForName(sel);

            elemNames = elemSetEnum.elemNames;
            unitNames = elemSetEnum.unitNames;

            handles.orbit1Label.String = elemNames{1};
            handles.orbit2Label.String = elemNames{2};
            handles.orbit3Label.String = elemNames{3};
            handles.orbit4Label.String = elemNames{4};
            handles.orbit5Label.String = elemNames{5};
            handles.orbit6Label.String = elemNames{6};

            handles.orbit1UnitLabel.String = unitNames{1};
            handles.orbit2UnitLabel.String = unitNames{2};
            handles.orbit3UnitLabel.String = unitNames{3};
            handles.orbit4UnitLabel.String = unitNames{4};
            handles.orbit5UnitLabel.String = unitNames{5};
            handles.orbit6UnitLabel.String = unitNames{6};

            curElemSet = getappdata(handles.lvd_EditInitialStateGUI,'curElemSet');

            try
                switch elemSetEnum
                    case ElementSetEnum.CartesianElements
                        newElemSet = curElemSet.convertToCartesianElementSet();

                    case ElementSetEnum.KeplerianElements
                        newElemSet = curElemSet.convertToKeplerianElementSet();

                    case ElementSetEnum.GeographicElements
                        newElemSet = curElemSet.convertToGeographicElementSet();

                    case ElementSetEnum.UniversalElements
                        newElemSet = curElemSet.convertToUniversalElementSet();

                    otherwise
                        error('Unknown element set type: %s', class(elemSetEnum));
                end

                elemVect = newElemSet.getElementVector();

                if(any(isnan(elemVect)) || any(not(isfinite(elemVect))))
                    error('State conversion resulted in NaN or Inf');
                end

                handles.orbit1Text.String = fullAccNum2Str(elemVect(1));
                handles.orbit2Text.String = fullAccNum2Str(elemVect(2));
                handles.orbit3Text.String = fullAccNum2Str(elemVect(3));
                handles.orbit4Text.String = fullAccNum2Str(elemVect(4));
                handles.orbit5Text.String = fullAccNum2Str(elemVect(5));
                handles.orbit6Text.String = fullAccNum2Str(elemVect(6));

                setappdata(handles.lvd_EditInitialStateGUI,'curElemSet', newElemSet);

            catch ME
                updateValuesInState(app, handles);
%                 msgbox('Error converting state to new element set.','Conversion Error','error');
                uialert(app.lvd_EditInitialStateGUI, 'Error converting state to new element set.', 'Conversion Error', "Icon","error");
            end
        end

        % Menu selected function: enterUTAsDateTimeContextMenu
        function enterUTAsDateTimeContextMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to enterUTAsDateTimeContextMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            secUT = enterUTAsDateTimeGUI(str2double(get(gco, 'String')));
            if(secUT >= 0)
                set(gco, 'String', fullAccNum2Str(secUT));
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                utText_Callback(app, evt);
            end
        end

        % Menu selected function: getOrbitFromKSPActiveVesselMenu
        function getOrbitFromKSPActiveVesselMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getOrbitFromKSPActiveVesselMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            contents = cellstr(get(handles.refFrameTypeCombo,'String'));
            selFrameType = contents{get(handles.refFrameTypeCombo,'Value')};
            enum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);
            if(not(enum == ReferenceFrameEnum.BodyCenteredInertial))
                enum = ReferenceFrameEnum.BodyCenteredInertial;
                ind = ReferenceFrameEnum.getIndForName(enum.name);
                handles.refFrameTypeCombo.Value = ind;
                refFrameTypeCombo_Callback(app, evt);
            end

            contents = cellstr(get(handles.elementSetCombo,'String'));
            selOrbitType = contents{get(handles.elementSetCombo,'Value')};
            [~,enum] = ElementSetEnum.getIndForName(selOrbitType);
            if(not(enum == ElementSetEnum.KeplerianElements))
                enum = ElementSetEnum.KeplerianElements;
                ind = ElementSetEnum.getIndForName(enum.name);
                handles.elementSetCombo.Value = ind;
                elementSetCombo_Callback(app, evt);
            end

            refBodyID = orbitPanelGetOrbitFromKSPTOTConnectActiveVesselCallBack(handles.orbit1Text, handles.orbit2Text, handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, handles.orbit6Text, handles.utText);
            tru = computeTrueAnomFromMean(deg2rad(str2double(get(handles.orbit6Text,'String'))), str2double(get(handles.orbit2Text,'String')));
            set(handles.orbit6Text,'String',fullAccNum2Str(rad2deg(tru)));

            if(~isempty(refBodyID) && isnumeric(refBodyID))
                lvdData = getappdata(handles.lvd_EditInitialStateGUI,'lvdData');
                celBodyData = lvdData.celBodyData;
                bodyInfo = getBodyInfoByNumber(refBodyID, celBodyData);

                curElemSet = getCurrentElemSetFromExistingValues(app, handles);
                setappdata(handles.lvd_EditInitialStateGUI,'curElemSet',curElemSet);

                curElemSet.frame.setOriginBody(bodyInfo);
                updateStateDueToFrameChange(app, handles, AbstractReferenceFrame.empty(1,0));
            end
        end

        % Menu selected function: 
        % getOrbitFromKSPTOTConnectContextMenu
        function getOrbitFromKSPTOTConnectContextMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getOrbitFromKSPTOTConnectContextMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            contents = cellstr(get(handles.refFrameTypeCombo,'String'));
            selFrameType = contents{get(handles.refFrameTypeCombo,'Value')};
            enum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);
            if(not(enum == ReferenceFrameEnum.BodyCenteredInertial))
                enum = ReferenceFrameEnum.BodyCenteredInertial;
                ind = ReferenceFrameEnum.getIndForName(enum.name);
                handles.refFrameTypeCombo.Value = ind;
                refFrameTypeCombo_Callback(app, evt);
            end

            contents = cellstr(get(handles.elementSetCombo,'String'));
            selOrbitType = contents{get(handles.elementSetCombo,'Value')};
            [~,enum] = ElementSetEnum.getIndForName(selOrbitType);
            if(not(enum == ElementSetEnum.KeplerianElements))
                enum = ElementSetEnum.KeplerianElements;
                ind = ElementSetEnum.getIndForName(enum.name);
                handles.elementSetCombo.Value = ind;
                elementSetCombo_Callback(app, evt);
            end

            refBodyID = orbitPanelGetOrbitFromKSPTOTConnectCallBack(handles.orbit1Text, handles.orbit2Text, handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, handles.orbit6Text, handles.utText);
            tru = computeTrueAnomFromMean(deg2rad(str2double(get(handles.orbit6Text,'String'))), str2double(get(handles.orbit2Text,'String')));
            set(handles.orbit6Text,'String',fullAccNum2Str(rad2deg(tru)));

            if(~isempty(refBodyID) && isnumeric(refBodyID))
                lvdData = getappdata(handles.lvd_EditInitialStateGUI,'lvdData');
                celBodyData = lvdData.celBodyData;
                bodyInfo = getBodyInfoByNumber(refBodyID, celBodyData);

                curElemSet = getCurrentElemSetFromExistingValues(app, handles);
                setappdata(handles.lvd_EditInitialStateGUI,'curElemSet',curElemSet);

                curElemSet.frame.setOriginBody(bodyInfo);
                updateStateDueToFrameChange(app, handles, AbstractReferenceFrame.empty(1,0));
            end
        end

        % Menu selected function: getOrbitFromSFSFileContextMenu
        function getOrbitFromSFSFileContextMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getOrbitFromSFSFileContextMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            contents = cellstr(get(handles.refFrameTypeCombo,'String'));
            selFrameType = contents{get(handles.refFrameTypeCombo,'Value')};
            enum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);
            if(not(enum == ReferenceFrameEnum.BodyCenteredInertial))
                enum = ReferenceFrameEnum.BodyCenteredInertial;
                ind = ReferenceFrameEnum.getIndForName(enum.name);
                handles.refFrameTypeCombo.Value = ind;
                refFrameTypeCombo_Callback(app, evt);
            end

            contents = cellstr(get(handles.elementSetCombo,'String'));
            selOrbitType = contents{get(handles.elementSetCombo,'Value')};
            [~,enum] = ElementSetEnum.getIndForName(selOrbitType);
            if(not(enum == ElementSetEnum.KeplerianElements))
                enum = ElementSetEnum.KeplerianElements;
                ind = ElementSetEnum.getIndForName(enum.name);
                handles.elementSetCombo.Value = ind;
                elementSetCombo_Callback(app, evt);
            end

            refBodyID = orbitPanelGetOrbitFromSFSContextCallBack(handles.ksptotMainGUI, handles.orbit1Text, handles.orbit2Text, handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, handles.orbit6Text, handles.utText);
            tru = computeTrueAnomFromMean(deg2rad(str2double(get(handles.orbit6Text,'String'))), str2double(get(handles.orbit2Text,'String')));
            set(handles.orbit6Text,'String',fullAccNum2Str(rad2deg(tru)));

            if(~isempty(refBodyID) && isnumeric(refBodyID))
                lvdData = getappdata(handles.lvd_EditInitialStateGUI,'lvdData');
                celBodyData = lvdData.celBodyData;
                bodyInfo = getBodyInfoByNumber(refBodyID, celBodyData);

                curElemSet = getCurrentElemSetFromExistingValues(app, handles);
                setappdata(handles.lvd_EditInitialStateGUI,'curElemSet',curElemSet);

                curElemSet.frame.setOriginBody(bodyInfo);
                updateStateDueToFrameChange(app, handles, AbstractReferenceFrame.empty(1,0));
            end
        end

        % Window key press function: lvd_EditInitialStateGUI
        function lvd_EditInitialStateGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditInitialStateGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    app.output.output = {false};
                    close(handles.lvd_EditInitialStateGUI);
            end
        end

        % Value changed function: optOrbit1Checkbox
        function optOrbit1Checkbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.optOrbit1Checkbox;
            
            % hObject    handle to optOrbit1Checkbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of optOrbit1Checkbox
            if(get(hObject,'Value'))
                handles.orbit1LbText.Enable = 'on';
                handles.orbit1UbText.Enable = 'on';
            else
                handles.orbit1LbText.Enable = 'off';
                handles.orbit1UbText.Enable = 'off';
            end
        end

        % Value changed function: optOrbit2Checkbox
        function optOrbit2Checkbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.optOrbit2Checkbox;
            
            % hObject    handle to optOrbit2Checkbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of optOrbit2Checkbox
            if(get(hObject,'Value'))
                handles.orbit2LbText.Enable = 'on';
                handles.orbit2UbText.Enable = 'on';
            else
                handles.orbit2LbText.Enable = 'off';
                handles.orbit2UbText.Enable = 'off';
            end
        end

        % Value changed function: optOrbit3Checkbox
        function optOrbit3Checkbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.optOrbit3Checkbox;
            
            % hObject    handle to optOrbit3Checkbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of optOrbit3Checkbox
            if(get(hObject,'Value'))
                handles.orbit3LbText.Enable = 'on';
                handles.orbit3UbText.Enable = 'on';
            else
                handles.orbit3LbText.Enable = 'off';
                handles.orbit3UbText.Enable = 'off';
            end
        end

        % Value changed function: optOrbit4Checkbox
        function optOrbit4Checkbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.optOrbit4Checkbox;
            
            % hObject    handle to optOrbit4Checkbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of optOrbit4Checkbox
            if(get(hObject,'Value'))
                handles.orbit4LbText.Enable = 'on';
                handles.orbit4UbText.Enable = 'on';
            else
                handles.orbit4LbText.Enable = 'off';
                handles.orbit4UbText.Enable = 'off';
            end
        end

        % Value changed function: optOrbit5Checkbox
        function optOrbit5Checkbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.optOrbit5Checkbox;
            
            % hObject    handle to optOrbit5Checkbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of optOrbit5Checkbox
            if(get(hObject,'Value'))
                handles.orbit5LbText.Enable = 'on';
                handles.orbit5UbText.Enable = 'on';
            else
                handles.orbit5LbText.Enable = 'off';
                handles.orbit5UbText.Enable = 'off';
            end
        end

        % Value changed function: optOrbit6Checkbox
        function optOrbit6Checkbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.optOrbit6Checkbox;
            
            % hObject    handle to optOrbit6Checkbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of optOrbit6Checkbox
            if(get(hObject,'Value'))
                handles.orbit6LbText.Enable = 'on';
                handles.orbit6UbText.Enable = 'on';
            else
                handles.orbit6LbText.Enable = 'off';
                handles.orbit6UbText.Enable = 'off';
            end
        end

        % Value changed function: optUtCheckbox
        function optUtCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.optUtCheckbox;
            
            % hObject    handle to optUtCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of optUtCheckbox
            if(get(hObject,'Value'))
                handles.utLbText.Enable = 'on';
                handles.utUbText.Enable = 'on';
            else
                handles.utLbText.Enable = 'off';
                handles.utUbText.Enable = 'off';
            end
        end

        % Value changed function: orbit1LbText
        function orbit1LbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit1LbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit1LbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit1LbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit1Text
        function orbit1Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit1Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit1Text as text
            %        str2double(get(hObject,'String')) returns contents of orbit1Text as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            updateValuesInState(app, handles);
        end

        % Value changed function: orbit1UbText
        function orbit1UbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit1UbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit1UbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit1UbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit2LbText
        function orbit2LbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit2LbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit2LbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit2LbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit2Text
        function orbit2Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit2Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit2Text as text
            %        str2double(get(hObject,'String')) returns contents of orbit2Text as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            updateValuesInState(app, handles);
        end

        % Value changed function: orbit2UbText
        function orbit2UbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit2UbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit2UbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit2UbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit3LbText
        function orbit3LbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit3LbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit3LbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit3LbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit3Text
        function orbit3Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit3Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit3Text as text
            %        str2double(get(hObject,'String')) returns contents of orbit3Text as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            updateValuesInState(app, handles);
        end

        % Value changed function: orbit3UbText
        function orbit3UbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit3UbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit3UbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit3UbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit4LbText
        function orbit4LbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit4LbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit4LbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit4LbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit4Text
        function orbit4Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit4Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit4Text as text
            %        str2double(get(hObject,'String')) returns contents of orbit4Text as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            updateValuesInState(app, handles);
        end

        % Value changed function: orbit4UbText
        function orbit4UbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit4UbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit4UbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit4UbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit5LbText
        function orbit5LbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit5LbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit5LbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit5LbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit5Text
        function orbit5Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit5Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit5Text as text
            %        str2double(get(hObject,'String')) returns contents of orbit5Text as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            updateValuesInState(app, handles);
        end

        % Value changed function: orbit5UbText
        function orbit5UbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit5UbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit5UbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit5UbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            updateValuesInState(app, handles);
        end

        % Value changed function: orbit6LbText
        function orbit6LbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit6LbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit6LbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit6LbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: orbit6Text
        function orbit6Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit6Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit6Text as text
            %        str2double(get(hObject,'String')) returns contents of orbit6Text as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            updateValuesInState(app, handles);
        end

        % Value changed function: orbit6UbText
        function orbit6UbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to orbit6UbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of orbit6UbText as text
            %        str2double(get(hObject,'String')) returns contents of orbit6UbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Menu selected function: pasteOrbitFromClipboardMenu
        function pasteOrbitFromClipboardMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pasteOrbitFromClipboardMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            contents = cellstr(get(handles.refFrameTypeCombo,'String'));
            selFrameType = contents{get(handles.refFrameTypeCombo,'Value')};
            enum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);
            if(not(enum == ReferenceFrameEnum.BodyCenteredInertial))
                enum = ReferenceFrameEnum.BodyCenteredInertial;
                ind = ReferenceFrameEnum.getIndForName(enum.name);
                handles.refFrameTypeCombo.Value = ind;
                refFrameTypeCombo_Callback(app, evt);
            end

            contents = cellstr(get(handles.elementSetCombo,'String'));
            selOrbitType = contents{get(handles.elementSetCombo,'Value')};
            [~,enum] = ElementSetEnum.getIndForName(selOrbitType);
            if(not(enum == ElementSetEnum.KeplerianElements))
                enum = ElementSetEnum.KeplerianElements;
                ind = ElementSetEnum.getIndForName(enum.name);
                handles.elementSetCombo.Value = ind;
                elementSetCombo_Callback(app, evt);
            end

            lvdData = getappdata(handles.lvd_EditInitialStateGUI,'lvdData');
            celBodyData = lvdData.celBodyData;

            bodyInfo = pasteOrbitFromClipboard(handles.utText, handles.orbit1Text, handles.orbit2Text, ...
                handles.orbit3Text, handles.orbit4Text, handles.orbit5Text, ...
                handles.orbit6Text, true, [], celBodyData);

            if(not(isempty(bodyInfo)))
                curElemSet = getappdata(handles.lvd_EditInitialStateGUI,'curElemSet');
                curElemSet.frame.setOriginBody(bodyInfo);
                updateStateDueToFrameChange(app, handles, AbstractReferenceFrame.empty(1,0));
            end
        end

        % Value changed function: refFrameTypeCombo
        function refFrameTypeCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.refFrameTypeCombo;
            
            % hObject    handle to refFrameTypeCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns refFrameTypeCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from refFrameTypeCombo
            updateStateDueToFrameChange(app, handles, AbstractReferenceFrame.empty(1,0));
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
%             hObject = handles.saveAndCloseButton;
            
            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                app.saveAndCloseFunction(handles);
            else
%                 msgbox(errMsg,'Errors were found while editing the initial state.','error');
                uialert(app.lvd_EditInitialStateGUI, errMsg, 'Errors were found while editing the initial state.', "Icon","error");
            end
        end

        % Button pushed function: setFrameOptionsButton
        function setFrameOptionsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to setFrameOptionsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            curElemSet = getappdata(handles.lvd_EditInitialStateGUI,'curElemSet');
            frame = curElemSet.frame;
            newFrame = frame.editFrameDialogUI(EditReferenceFrameContextEnum.ForState);

            updateStateDueToFrameChange(app, handles, newFrame);
        end

        % Value changed function: utLbText
        function utLbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to utLbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of utLbText as text
            %        str2double(get(hObject,'String')) returns contents of utLbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: utText
        function utText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.utText;
            
            % hObject    handle to utText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of utText as text
            %        str2double(get(hObject,'String')) returns contents of utText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            updateValuesInState(app, handles);
        end

        % Value changed function: utUbText
        function utUbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to utUbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of utUbText as text
            %        str2double(get(hObject,'String')) returns contents of utUbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditInitialStateGUI = uifigure('Visible', 'off');
            app.lvd_EditInitialStateGUI.Name = 'Edit Initial State';
            app.lvd_EditInitialStateGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditInitialStateGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditInitialStateGUI_WindowKeyPressFcn, true);
            app.lvd_EditInitialStateGUI.Tag = 'lvd_EditInitialStateGUI';
            app.GridLayout3 = uigridlayout(app.lvd_EditInitialStateGUI);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.saveAndCloseButton = uibutton(app.GridLayout4, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout4, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout3);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout = uigridlayout(app.uipanel1);
            app.uipanel3 = uipanel(app.GridLayout);
            app.uipanel3.Title = 'Orbit Parameters';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout2 = uigridlayout(app.uipanel3);
            app.text17 = uilabel(app.GridLayout2);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Element Set';
            app.text24 = uilabel(app.GridLayout2);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Frame Type';
            app.elementSetCombo = uidropdown(app.GridLayout2);
            app.elementSetCombo.Items = {'Pop-up Menu'};
            app.elementSetCombo.ValueChangedFcn = createCallbackFcn(app, @elementSetCombo_Callback, true);
            app.elementSetCombo.Tag = 'elementSetCombo';
            app.elementSetCombo.Tooltip = 'The elements used to define the state (position and velocity) of the vehicle.';
            app.elementSetCombo.Value = 'Pop-up Menu';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.setFrameOptionsButton = uibutton(app.GridLayout5, 'push');
            app.setFrameOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @setFrameOptionsButton_Callback, true);
            app.setFrameOptionsButton.Tag = 'setFrameOptionsButton';
            app.setFrameOptionsButton.Text = 'Set Frame Options';
            app.refFrameTypeCombo = uidropdown(app.GridLayout5);
            app.refFrameTypeCombo.Items = {'Pop-up Menu'};
            app.refFrameTypeCombo.ValueChangedFcn = createCallbackFcn(app, @refFrameTypeCombo_Callback, true);
            app.refFrameTypeCombo.Tag = 'refFrameTypeCombo';
            app.refFrameTypeCombo.Tooltip = 'The type of reference frame used with the selected element set, relative to the selected central body.  Generally, use Keplerian elements with an inertial frame and geographic elements with a body-fixed frame.';
            app.refFrameTypeCombo.Value = 'Pop-up Menu';
            app.uipanel7 = uipanel(app.GridLayout);
            app.uipanel7.Title = 'Steering & Throttle';
            app.uipanel7.Tag = 'uipanel7';
            app.GridLayout6 = uigridlayout(app.uipanel7);
            app.editSteeringButton = uibutton(app.GridLayout6, 'push');
            app.editSteeringButton.ButtonPushedFcn = createCallbackFcn(app, @editSteeringButton_Callback, true);
            app.editSteeringButton.Tag = 'editSteeringButton';
            app.editSteeringButton.Text = 'Edit Steering Initial State';
            app.editThrottleButton = uibutton(app.GridLayout6, 'push');
            app.editThrottleButton.ButtonPushedFcn = createCallbackFcn(app, @editThrottleButton_Callback, true);
            app.editThrottleButton.Tag = 'editThrottleButton';
            app.editThrottleButton.Text = 'Edit Throttle Initial State';
            app.uipanel8 = uipanel(app.GridLayout);
            app.uipanel8.Title = '3rd Body Gravity Properties';
            app.uipanel8.Tag = 'uipanel8';
            app.GridLayout7 = uigridlayout(app.uipanel8);
            app.edit3BodyGravPropertiesButton = uibutton(app.GridLayout7, 'push');
            app.edit3BodyGravPropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @edit3BodyGravPropertiesButton_Callback, true);
            app.edit3BodyGravPropertiesButton.Tag = 'edit3BodyGravPropertiesButton';
            app.edit3BodyGravPropertiesButton.Tooltip = 'Edit the bodies that can be used as 3rd body gravity sources.  ONLY used when the Third Body Gravity force model is active on events.  Otherwise, has no impact.';
            app.edit3BodyGravPropertiesButton.Text = 'Edit 3rd Body Gravity Properties';
            app.uipanel5 = uipanel(app.GridLayout);
            app.uipanel5.Title = 'Aerodynamics State';
            app.uipanel5.Tag = 'uipanel5';
            app.GridLayout8 = uigridlayout(app.uipanel5);
            app.editDragPropsButton = uibutton(app.GridLayout8, 'push');
            app.editDragPropsButton.ButtonPushedFcn = createCallbackFcn(app, @editDragPropsButton_Callback, true);
            app.editDragPropsButton.Tag = 'editDragPropsButton';
            app.editDragPropsButton.Text = 'Edit Drag Properties';
            app.editLiftPropsButton = uibutton(app.GridLayout8, 'push');
            app.editLiftPropsButton.ButtonPushedFcn = createCallbackFcn(app, @editLiftPropsButton_Callback, true);
            app.editLiftPropsButton.Tag = 'editLiftPropsButton';
            app.editLiftPropsButton.Text = 'Edit Lift Properties';
            app.uipanel4 = uipanel(app.GridLayout);
            app.uipanel4.Title = 'Launch Vehicle & Stage States';
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout9 = uigridlayout(app.uipanel4);
            app.editLvAndStageStatesButton = uibutton(app.GridLayout9, 'push');
            app.editLvAndStageStatesButton.ButtonPushedFcn = createCallbackFcn(app, @editLvAndStageStatesButton_Callback, true);
            app.editLvAndStageStatesButton.Tag = 'editLvAndStageStatesButton';
            app.editLvAndStageStatesButton.Text = 'Edit Launch Vehicle & Stage States';
            app.uipanel2 = uipanel(app.GridLayout);
            app.uipanel2.Title = 'Orbit';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout10 = uigridlayout(app.uipanel2);
            app.text3 = uilabel(app.GridLayout10);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Epoch (UT)';
            app.orbit1Label = uilabel(app.GridLayout10);
            app.orbit1Label.Tag = 'orbit1Label';
            app.orbit1Label.Text = 'Orbit1';
            app.orbit2Label = uilabel(app.GridLayout10);
            app.orbit2Label.Tag = 'orbit2Label';
            app.orbit2Label.Text = 'Orbit2';
            app.orbit3Label = uilabel(app.GridLayout10);
            app.orbit3Label.Tag = 'orbit3Label';
            app.orbit3Label.Text = 'Orbit3';
            app.orbit4Label = uilabel(app.GridLayout10);
            app.orbit4Label.Tag = 'orbit4Label';
            app.orbit4Label.Text = 'Orbit4';
            app.orbit5Label = uilabel(app.GridLayout10);
            app.orbit5Label.Tag = 'orbit5Label';
            app.orbit5Label.Text = 'Orbit5';
            app.orbit6Label = uilabel(app.GridLayout10);
            app.orbit6Label.Tag = 'orbit6Label';
            app.orbit6Label.Text = 'Orbit6';
            app.utUnitLabel = uilabel(app.GridLayout10);
            app.utUnitLabel.Tag = 'utUnitLabel';
            app.utUnitLabel.Text = 'sec';
            app.orbit1UnitLabel = uilabel(app.GridLayout10);
            app.orbit1UnitLabel.Tag = 'orbit1UnitLabel';
            app.orbit1UnitLabel.Text = '';
            app.orbit2UnitLabel = uilabel(app.GridLayout10);
            app.orbit2UnitLabel.Tag = 'orbit2UnitLabel';
            app.orbit2UnitLabel.Text = '';
            app.orbit3UnitLabel = uilabel(app.GridLayout10);
            app.orbit3UnitLabel.Tag = 'orbit3UnitLabel';
            app.orbit3UnitLabel.Text = '';
            app.orbit4UnitLabel = uilabel(app.GridLayout10);
            app.orbit4UnitLabel.Tag = 'orbit4UnitLabel';
            app.orbit4UnitLabel.Text = '';
            app.orbit5UnitLabel = uilabel(app.GridLayout10);
            app.orbit5UnitLabel.Tag = 'orbit5UnitLabel';
            app.orbit5UnitLabel.Text = '';
            app.orbit6UnitLabel = uilabel(app.GridLayout10);
            app.orbit6UnitLabel.Tag = 'orbit6UnitLabel';
            app.orbit6UnitLabel.Text = '';
            app.optUtCheckbox = uicheckbox(app.GridLayout10);
            app.optUtCheckbox.ValueChangedFcn = createCallbackFcn(app, @optUtCheckbox_Callback, true);
            app.optUtCheckbox.Tag = 'optUtCheckbox';
            app.optUtCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.optUtCheckbox.Text = 'Opt?';
            app.optUtCheckbox.Value = true;
            app.optOrbit1Checkbox = uicheckbox(app.GridLayout10);
            app.optOrbit1Checkbox.ValueChangedFcn = createCallbackFcn(app, @optOrbit1Checkbox_Callback, true);
            app.optOrbit1Checkbox.Tag = 'optOrbit1Checkbox';
            app.optOrbit1Checkbox.Tooltip = 'Select to optimize this quantity.';
            app.optOrbit1Checkbox.Text = 'Opt?';
            app.optOrbit1Checkbox.Value = true;
            app.optOrbit2Checkbox = uicheckbox(app.GridLayout10);
            app.optOrbit2Checkbox.ValueChangedFcn = createCallbackFcn(app, @optOrbit2Checkbox_Callback, true);
            app.optOrbit2Checkbox.Tag = 'optOrbit2Checkbox';
            app.optOrbit2Checkbox.Tooltip = 'Select to optimize this quantity.';
            app.optOrbit2Checkbox.Text = 'Opt?';
            app.optOrbit2Checkbox.Value = true;
            app.optOrbit3Checkbox = uicheckbox(app.GridLayout10);
            app.optOrbit3Checkbox.ValueChangedFcn = createCallbackFcn(app, @optOrbit3Checkbox_Callback, true);
            app.optOrbit3Checkbox.Tag = 'optOrbit3Checkbox';
            app.optOrbit3Checkbox.Tooltip = 'Select to optimize this quantity.';
            app.optOrbit3Checkbox.Text = 'Opt?';
            app.optOrbit3Checkbox.Value = true;
            app.optOrbit4Checkbox = uicheckbox(app.GridLayout10);
            app.optOrbit4Checkbox.ValueChangedFcn = createCallbackFcn(app, @optOrbit4Checkbox_Callback, true);
            app.optOrbit4Checkbox.Tag = 'optOrbit4Checkbox';
            app.optOrbit4Checkbox.Tooltip = 'Select to optimize this quantity.';
            app.optOrbit4Checkbox.Text = 'Opt?';
            app.optOrbit4Checkbox.Value = true;
            app.optOrbit5Checkbox = uicheckbox(app.GridLayout10);
            app.optOrbit5Checkbox.ValueChangedFcn = createCallbackFcn(app, @optOrbit5Checkbox_Callback, true);
            app.optOrbit5Checkbox.Tag = 'optOrbit5Checkbox';
            app.optOrbit5Checkbox.Tooltip = 'Select to optimize this quantity.';
            app.optOrbit5Checkbox.Text = 'Opt?';
            app.optOrbit5Checkbox.Value = true;
            app.optOrbit6Checkbox = uicheckbox(app.GridLayout10);
            app.optOrbit6Checkbox.ValueChangedFcn = createCallbackFcn(app, @optOrbit6Checkbox_Callback, true);
            app.optOrbit6Checkbox.Tag = 'optOrbit6Checkbox';
            app.optOrbit6Checkbox.Tooltip = 'Select to optimize this quantity.';
            app.optOrbit6Checkbox.Text = 'Opt?';
            app.optOrbit6Checkbox.Value = true;
            app.utText = uieditfield(app.GridLayout10, 'text');
            app.utText.ValueChangedFcn = createCallbackFcn(app, @utText_Callback, true);
            app.utText.Tag = 'utText';
            app.utText.Value = '0.0';
            app.orbit1Text = uieditfield(app.GridLayout10, 'text');
            app.orbit1Text.ValueChangedFcn = createCallbackFcn(app, @orbit1Text_Callback, true);
            app.orbit1Text.Tag = 'orbit1Text';
            app.orbit1Text.Value = '0.0';
            app.orbit2Text = uieditfield(app.GridLayout10, 'text');
            app.orbit2Text.ValueChangedFcn = createCallbackFcn(app, @orbit2Text_Callback, true);
            app.orbit2Text.Tag = 'orbit2Text';
            app.orbit2Text.Value = '0.0';
            app.orbit3Text = uieditfield(app.GridLayout10, 'text');
            app.orbit3Text.ValueChangedFcn = createCallbackFcn(app, @orbit3Text_Callback, true);
            app.orbit3Text.Tag = 'orbit3Text';
            app.orbit3Text.Value = '0.0';
            app.orbit4Text = uieditfield(app.GridLayout10, 'text');
            app.orbit4Text.ValueChangedFcn = createCallbackFcn(app, @orbit4Text_Callback, true);
            app.orbit4Text.Tag = 'orbit4Text';
            app.orbit4Text.Value = '0.0';
            app.orbit5Text = uieditfield(app.GridLayout10, 'text');
            app.orbit5Text.ValueChangedFcn = createCallbackFcn(app, @orbit5Text_Callback, true);
            app.orbit5Text.Tag = 'orbit5Text';
            app.orbit5Text.Value = '0.0';
            app.orbit6Text = uieditfield(app.GridLayout10, 'text');
            app.orbit6Text.ValueChangedFcn = createCallbackFcn(app, @orbit6Text_Callback, true);
            app.orbit6Text.Tag = 'orbit6Text';
            app.orbit6Text.Value = '0.0';
            app.utLbText = uieditfield(app.GridLayout10, 'text');
            app.utLbText.ValueChangedFcn = createCallbackFcn(app, @utLbText_Callback, true);
            app.utLbText.Tag = 'utLbText';
            app.utLbText.Tooltip = 'Parameter lower bound.';
            app.utLbText.Value = '0.0';
            app.orbit1LbText = uieditfield(app.GridLayout10, 'text');
            app.orbit1LbText.ValueChangedFcn = createCallbackFcn(app, @orbit1LbText_Callback, true);
            app.orbit1LbText.Tag = 'orbit1LbText';
            app.orbit1LbText.Tooltip = 'Parameter lower bound.';
            app.orbit1LbText.Value = '0.0';
            app.orbit2LbText = uieditfield(app.GridLayout10, 'text');
            app.orbit2LbText.ValueChangedFcn = createCallbackFcn(app, @orbit2LbText_Callback, true);
            app.orbit2LbText.Tag = 'orbit2LbText';
            app.orbit2LbText.Tooltip = 'Parameter lower bound.';
            app.orbit2LbText.Value = '0.0';
            app.orbit3LbText = uieditfield(app.GridLayout10, 'text');
            app.orbit3LbText.ValueChangedFcn = createCallbackFcn(app, @orbit3LbText_Callback, true);
            app.orbit3LbText.Tag = 'orbit3LbText';
            app.orbit3LbText.Tooltip = 'Parameter lower bound.';
            app.orbit3LbText.Value = '0.0';
            app.orbit4LbText = uieditfield(app.GridLayout10, 'text');
            app.orbit4LbText.ValueChangedFcn = createCallbackFcn(app, @orbit4LbText_Callback, true);
            app.orbit4LbText.Tag = 'orbit4LbText';
            app.orbit4LbText.Tooltip = 'Parameter lower bound.';
            app.orbit4LbText.Value = '0.0';
            app.orbit5LbText = uieditfield(app.GridLayout10, 'text');
            app.orbit5LbText.ValueChangedFcn = createCallbackFcn(app, @orbit5LbText_Callback, true);
            app.orbit5LbText.Tag = 'orbit5LbText';
            app.orbit5LbText.Tooltip = 'Parameter lower bound.';
            app.orbit5LbText.Value = '0.0';
            app.orbit6LbText = uieditfield(app.GridLayout10, 'text');
            app.orbit6LbText.ValueChangedFcn = createCallbackFcn(app, @orbit6LbText_Callback, true);
            app.orbit6LbText.Tag = 'orbit6LbText';
            app.orbit6LbText.Tooltip = 'Parameter lower bound.';
            app.orbit6LbText.Value = '0.0';
            app.utUbText = uieditfield(app.GridLayout10, 'text');
            app.utUbText.ValueChangedFcn = createCallbackFcn(app, @utUbText_Callback, true);
            app.utUbText.Tag = 'utUbText';
            app.utUbText.Tooltip = 'Parameter upper bound.';
            app.utUbText.Value = '0.0';
            app.orbit1UbText = uieditfield(app.GridLayout10, 'text');
            app.orbit1UbText.ValueChangedFcn = createCallbackFcn(app, @orbit1UbText_Callback, true);
            app.orbit1UbText.Tag = 'orbit1UbText';
            app.orbit1UbText.Tooltip = 'Parameter upper bound.';
            app.orbit1UbText.Value = '0.0';
            app.orbit2UbText = uieditfield(app.GridLayout10, 'text');
            app.orbit2UbText.ValueChangedFcn = createCallbackFcn(app, @orbit2UbText_Callback, true);
            app.orbit2UbText.Tag = 'orbit2UbText';
            app.orbit2UbText.Tooltip = 'Parameter upper bound.';
            app.orbit2UbText.Value = '0.0';
            app.orbit3UbText = uieditfield(app.GridLayout10, 'text');
            app.orbit3UbText.ValueChangedFcn = createCallbackFcn(app, @orbit3UbText_Callback, true);
            app.orbit3UbText.Tag = 'orbit3UbText';
            app.orbit3UbText.Tooltip = 'Parameter upper bound.';
            app.orbit3UbText.Value = '0.0';
            app.orbit4UbText = uieditfield(app.GridLayout10, 'text');
            app.orbit4UbText.ValueChangedFcn = createCallbackFcn(app, @orbit4UbText_Callback, true);
            app.orbit4UbText.Tag = 'orbit4UbText';
            app.orbit4UbText.Tooltip = 'Parameter upper bound.';
            app.orbit4UbText.Value = '0.0';
            app.orbit5UbText = uieditfield(app.GridLayout10, 'text');
            app.orbit5UbText.ValueChangedFcn = createCallbackFcn(app, @orbit5UbText_Callback, true);
            app.orbit5UbText.Tag = 'orbit5UbText';
            app.orbit5UbText.Tooltip = 'Parameter upper bound.';
            app.orbit5UbText.Value = '0.0';
            app.orbit6UbText = uieditfield(app.GridLayout10, 'text');
            app.orbit6UbText.ValueChangedFcn = createCallbackFcn(app, @orbit6UbText_Callback, true);
            app.orbit6UbText.Tag = 'orbit6UbText';
            app.orbit6UbText.Tooltip = 'Parameter upper bound.';
            app.orbit6UbText.Value = '0.0';
            app.uipanel1 = uipanel(app.GridLayout3);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout = uigridlayout(app.uipanel1);
            app.uipanel3 = uipanel(app.GridLayout);
            app.uipanel3.Title = 'Orbit Parameters';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout2 = uigridlayout(app.uipanel3);
            app.text17 = uilabel(app.GridLayout2);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Element Set';
            app.text24 = uilabel(app.GridLayout2);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Frame Type';
            app.elementSetCombo = uidropdown(app.GridLayout2);
            app.elementSetCombo.Items = {'Pop-up Menu'};
            app.elementSetCombo.ValueChangedFcn = createCallbackFcn(app, @elementSetCombo_Callback, true);
            app.elementSetCombo.Tag = 'elementSetCombo';
            app.elementSetCombo.Tooltip = 'The elements used to define the state (position and velocity) of the vehicle.';
            app.elementSetCombo.Value = 'Pop-up Menu';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.setFrameOptionsButton = uibutton(app.GridLayout5, 'push');
            app.setFrameOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @setFrameOptionsButton_Callback, true);
            app.setFrameOptionsButton.Tag = 'setFrameOptionsButton';
            app.setFrameOptionsButton.Text = 'Set Frame Options';
            app.refFrameTypeCombo = uidropdown(app.GridLayout5);
            app.refFrameTypeCombo.Items = {'Pop-up Menu'};
            app.refFrameTypeCombo.ValueChangedFcn = createCallbackFcn(app, @refFrameTypeCombo_Callback, true);
            app.refFrameTypeCombo.Tag = 'refFrameTypeCombo';
            app.refFrameTypeCombo.Tooltip = 'The type of reference frame used with the selected element set, relative to the selected central body.  Generally, use Keplerian elements with an inertial frame and geographic elements with a body-fixed frame.';
            app.refFrameTypeCombo.Value = 'Pop-up Menu';
            app.uipanel7 = uipanel(app.GridLayout);
            app.uipanel7.Title = 'Steering & Throttle';
            app.uipanel7.Tag = 'uipanel7';
            app.GridLayout6 = uigridlayout(app.uipanel7);
            app.editSteeringButton = uibutton(app.GridLayout6, 'push');
            app.editSteeringButton.ButtonPushedFcn = createCallbackFcn(app, @editSteeringButton_Callback, true);
            app.editSteeringButton.Tag = 'editSteeringButton';
            app.editSteeringButton.Text = 'Edit Steering Initial State';
            app.editThrottleButton = uibutton(app.GridLayout6, 'push');
            app.editThrottleButton.ButtonPushedFcn = createCallbackFcn(app, @editThrottleButton_Callback, true);
            app.editThrottleButton.Tag = 'editThrottleButton';
            app.editThrottleButton.Text = 'Edit Throttle Initial State';
            app.uipanel8 = uipanel(app.GridLayout);
            app.uipanel8.Title = '3rd Body Gravity Properties';
            app.uipanel8.Tag = 'uipanel8';
            app.GridLayout7 = uigridlayout(app.uipanel8);
            app.edit3BodyGravPropertiesButton = uibutton(app.GridLayout7, 'push');
            app.edit3BodyGravPropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @edit3BodyGravPropertiesButton_Callback, true);
            app.edit3BodyGravPropertiesButton.Tag = 'edit3BodyGravPropertiesButton';
            app.edit3BodyGravPropertiesButton.Tooltip = 'Edit the bodies that can be used as 3rd body gravity sources.  ONLY used when the Third Body Gravity force model is active on events.  Otherwise, has no impact.';
            app.edit3BodyGravPropertiesButton.Text = 'Edit 3rd Body Gravity Properties';
            app.uipanel5 = uipanel(app.GridLayout);
            app.uipanel5.Title = 'Aerodynamics State';
            app.uipanel5.Tag = 'uipanel5';
            app.GridLayout8 = uigridlayout(app.uipanel5);
            app.editDragPropsButton = uibutton(app.GridLayout8, 'push');
            app.editDragPropsButton.ButtonPushedFcn = createCallbackFcn(app, @editDragPropsButton_Callback, true);
            app.editDragPropsButton.Tag = 'editDragPropsButton';
            app.editDragPropsButton.Text = 'Edit Drag Properties';
            app.editLiftPropsButton = uibutton(app.GridLayout8, 'push');
            app.editLiftPropsButton.ButtonPushedFcn = createCallbackFcn(app, @editLiftPropsButton_Callback, true);
            app.editLiftPropsButton.Tag = 'editLiftPropsButton';
            app.editLiftPropsButton.Text = 'Edit Lift Properties';
            app.uipanel4 = uipanel(app.GridLayout);
            app.uipanel4.Title = 'Launch Vehicle & Stage States';
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout9 = uigridlayout(app.uipanel4);
            app.editLvAndStageStatesButton = uibutton(app.GridLayout9, 'push');
            app.editLvAndStageStatesButton.ButtonPushedFcn = createCallbackFcn(app, @editLvAndStageStatesButton_Callback, true);
            app.editLvAndStageStatesButton.Tag = 'editLvAndStageStatesButton';
            app.editLvAndStageStatesButton.Text = 'Edit Launch Vehicle & Stage States';
            app.uipanel2 = uipanel(app.GridLayout);
            app.uipanel2.Title = 'Orbit';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout10 = uigridlayout(app.uipanel2);
            app.text3 = uilabel(app.GridLayout10);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Epoch (UT)';
            app.orbit1Label = uilabel(app.GridLayout10);
            app.orbit1Label.Tag = 'orbit1Label';
            app.orbit1Label.Text = 'Orbit1';
            app.orbit2Label = uilabel(app.GridLayout10);
            app.orbit2Label.Tag = 'orbit2Label';
            app.orbit2Label.Text = 'Orbit2';
            app.orbit3Label = uilabel(app.GridLayout10);
            app.orbit3Label.Tag = 'orbit3Label';
            app.orbit3Label.Text = 'Orbit3';
            app.orbit4Label = uilabel(app.GridLayout10);
            app.orbit4Label.Tag = 'orbit4Label';
            app.orbit4Label.Text = 'Orbit4';
            app.orbit5Label = uilabel(app.GridLayout10);
            app.orbit5Label.Tag = 'orbit5Label';
            app.orbit5Label.Text = 'Orbit5';
            app.orbit6Label = uilabel(app.GridLayout10);
            app.orbit6Label.Tag = 'orbit6Label';
            app.orbit6Label.Text = 'Orbit6';
            app.utUnitLabel = uilabel(app.GridLayout10);
            app.utUnitLabel.Tag = 'utUnitLabel';
            app.utUnitLabel.Text = 'sec';
            app.orbit1UnitLabel = uilabel(app.GridLayout10);
            app.orbit1UnitLabel.Tag = 'orbit1UnitLabel';
            app.orbit1UnitLabel.Text = '';
            app.orbit2UnitLabel = uilabel(app.GridLayout10);
            app.orbit2UnitLabel.Tag = 'orbit2UnitLabel';
            app.orbit2UnitLabel.Text = '';
            app.orbit3UnitLabel = uilabel(app.GridLayout10);
            app.orbit3UnitLabel.Tag = 'orbit3UnitLabel';
            app.orbit3UnitLabel.Text = '';
            app.orbit4UnitLabel = uilabel(app.GridLayout10);
            app.orbit4UnitLabel.Tag = 'orbit4UnitLabel';
            app.orbit4UnitLabel.Text = '';
            app.orbit5UnitLabel = uilabel(app.GridLayout10);
            app.orbit5UnitLabel.Tag = 'orbit5UnitLabel';
            app.orbit5UnitLabel.Text = '';
            app.orbit6UnitLabel = uilabel(app.GridLayout10);
            app.orbit6UnitLabel.Tag = 'orbit6UnitLabel';
            app.orbit6UnitLabel.Text = '';
            app.optUtCheckbox = uicheckbox(app.GridLayout10);
            app.optUtCheckbox.ValueChangedFcn = createCallbackFcn(app, @optUtCheckbox_Callback, true);
            app.optUtCheckbox.Tag = 'optUtCheckbox';
            app.optUtCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.optUtCheckbox.Text = 'Opt?';
            app.optUtCheckbox.Value = true;
            app.optOrbit1Checkbox = uicheckbox(app.GridLayout10);
            app.optOrbit1Checkbox.ValueChangedFcn = createCallbackFcn(app, @optOrbit1Checkbox_Callback, true);
            app.optOrbit1Checkbox.Tag = 'optOrbit1Checkbox';
            app.optOrbit1Checkbox.Tooltip = 'Select to optimize this quantity.';
            app.optOrbit1Checkbox.Text = 'Opt?';
            app.optOrbit1Checkbox.Value = true;
            app.optOrbit2Checkbox = uicheckbox(app.GridLayout10);
            app.optOrbit2Checkbox.ValueChangedFcn = createCallbackFcn(app, @optOrbit2Checkbox_Callback, true);
            app.optOrbit2Checkbox.Tag = 'optOrbit2Checkbox';
            app.optOrbit2Checkbox.Tooltip = 'Select to optimize this quantity.';
            app.optOrbit2Checkbox.Text = 'Opt?';
            app.optOrbit2Checkbox.Value = true;
            app.optOrbit3Checkbox = uicheckbox(app.GridLayout10);
            app.optOrbit3Checkbox.ValueChangedFcn = createCallbackFcn(app, @optOrbit3Checkbox_Callback, true);
            app.optOrbit3Checkbox.Tag = 'optOrbit3Checkbox';
            app.optOrbit3Checkbox.Tooltip = 'Select to optimize this quantity.';
            app.optOrbit3Checkbox.Text = 'Opt?';
            app.optOrbit3Checkbox.Value = true;
            app.optOrbit4Checkbox = uicheckbox(app.GridLayout10);
            app.optOrbit4Checkbox.ValueChangedFcn = createCallbackFcn(app, @optOrbit4Checkbox_Callback, true);
            app.optOrbit4Checkbox.Tag = 'optOrbit4Checkbox';
            app.optOrbit4Checkbox.Tooltip = 'Select to optimize this quantity.';
            app.optOrbit4Checkbox.Text = 'Opt?';
            app.optOrbit4Checkbox.Value = true;
            app.optOrbit5Checkbox = uicheckbox(app.GridLayout10);
            app.optOrbit5Checkbox.ValueChangedFcn = createCallbackFcn(app, @optOrbit5Checkbox_Callback, true);
            app.optOrbit5Checkbox.Tag = 'optOrbit5Checkbox';
            app.optOrbit5Checkbox.Tooltip = 'Select to optimize this quantity.';
            app.optOrbit5Checkbox.Text = 'Opt?';
            app.optOrbit5Checkbox.Value = true;
            app.optOrbit6Checkbox = uicheckbox(app.GridLayout10);
            app.optOrbit6Checkbox.ValueChangedFcn = createCallbackFcn(app, @optOrbit6Checkbox_Callback, true);
            app.optOrbit6Checkbox.Tag = 'optOrbit6Checkbox';
            app.optOrbit6Checkbox.Tooltip = 'Select to optimize this quantity.';
            app.optOrbit6Checkbox.Text = 'Opt?';
            app.optOrbit6Checkbox.Value = true;
            app.utText = uieditfield(app.GridLayout10, 'text');
            app.utText.ValueChangedFcn = createCallbackFcn(app, @utText_Callback, true);
            app.utText.Tag = 'utText';
            app.utText.Value = '0.0';
            app.orbit1Text = uieditfield(app.GridLayout10, 'text');
            app.orbit1Text.ValueChangedFcn = createCallbackFcn(app, @orbit1Text_Callback, true);
            app.orbit1Text.Tag = 'orbit1Text';
            app.orbit1Text.Value = '0.0';
            app.orbit2Text = uieditfield(app.GridLayout10, 'text');
            app.orbit2Text.ValueChangedFcn = createCallbackFcn(app, @orbit2Text_Callback, true);
            app.orbit2Text.Tag = 'orbit2Text';
            app.orbit2Text.Value = '0.0';
            app.orbit3Text = uieditfield(app.GridLayout10, 'text');
            app.orbit3Text.ValueChangedFcn = createCallbackFcn(app, @orbit3Text_Callback, true);
            app.orbit3Text.Tag = 'orbit3Text';
            app.orbit3Text.Value = '0.0';
            app.orbit4Text = uieditfield(app.GridLayout10, 'text');
            app.orbit4Text.ValueChangedFcn = createCallbackFcn(app, @orbit4Text_Callback, true);
            app.orbit4Text.Tag = 'orbit4Text';
            app.orbit4Text.Value = '0.0';
            app.orbit5Text = uieditfield(app.GridLayout10, 'text');
            app.orbit5Text.ValueChangedFcn = createCallbackFcn(app, @orbit5Text_Callback, true);
            app.orbit5Text.Tag = 'orbit5Text';
            app.orbit5Text.Value = '0.0';
            app.orbit6Text = uieditfield(app.GridLayout10, 'text');
            app.orbit6Text.ValueChangedFcn = createCallbackFcn(app, @orbit6Text_Callback, true);
            app.orbit6Text.Tag = 'orbit6Text';
            app.orbit6Text.Value = '0.0';
            app.utLbText = uieditfield(app.GridLayout10, 'text');
            app.utLbText.ValueChangedFcn = createCallbackFcn(app, @utLbText_Callback, true);
            app.utLbText.Tag = 'utLbText';
            app.utLbText.Tooltip = 'Parameter lower bound.';
            app.utLbText.Value = '0.0';
            app.orbit1LbText = uieditfield(app.GridLayout10, 'text');
            app.orbit1LbText.ValueChangedFcn = createCallbackFcn(app, @orbit1LbText_Callback, true);
            app.orbit1LbText.Tag = 'orbit1LbText';
            app.orbit1LbText.Tooltip = 'Parameter lower bound.';
            app.orbit1LbText.Value = '0.0';
            app.orbit2LbText = uieditfield(app.GridLayout10, 'text');
            app.orbit2LbText.ValueChangedFcn = createCallbackFcn(app, @orbit2LbText_Callback, true);
            app.orbit2LbText.Tag = 'orbit2LbText';
            app.orbit2LbText.Tooltip = 'Parameter lower bound.';
            app.orbit2LbText.Value = '0.0';
            app.orbit3LbText = uieditfield(app.GridLayout10, 'text');
            app.orbit3LbText.ValueChangedFcn = createCallbackFcn(app, @orbit3LbText_Callback, true);
            app.orbit3LbText.Tag = 'orbit3LbText';
            app.orbit3LbText.Tooltip = 'Parameter lower bound.';
            app.orbit3LbText.Value = '0.0';
            app.orbit4LbText = uieditfield(app.GridLayout10, 'text');
            app.orbit4LbText.ValueChangedFcn = createCallbackFcn(app, @orbit4LbText_Callback, true);
            app.orbit4LbText.Tag = 'orbit4LbText';
            app.orbit4LbText.Tooltip = 'Parameter lower bound.';
            app.orbit4LbText.Value = '0.0';
            app.orbit5LbText = uieditfield(app.GridLayout10, 'text');
            app.orbit5LbText.ValueChangedFcn = createCallbackFcn(app, @orbit5LbText_Callback, true);
            app.orbit5LbText.Tag = 'orbit5LbText';
            app.orbit5LbText.Tooltip = 'Parameter lower bound.';
            app.orbit5LbText.Value = '0.0';
            app.orbit6LbText = uieditfield(app.GridLayout10, 'text');
            app.orbit6LbText.ValueChangedFcn = createCallbackFcn(app, @orbit6LbText_Callback, true);
            app.orbit6LbText.Tag = 'orbit6LbText';
            app.orbit6LbText.Tooltip = 'Parameter lower bound.';
            app.orbit6LbText.Value = '0.0';
            app.utUbText = uieditfield(app.GridLayout10, 'text');
            app.utUbText.ValueChangedFcn = createCallbackFcn(app, @utUbText_Callback, true);
            app.utUbText.Tag = 'utUbText';
            app.utUbText.Tooltip = 'Parameter upper bound.';
            app.utUbText.Value = '0.0';
            app.orbit1UbText = uieditfield(app.GridLayout10, 'text');
            app.orbit1UbText.ValueChangedFcn = createCallbackFcn(app, @orbit1UbText_Callback, true);
            app.orbit1UbText.Tag = 'orbit1UbText';
            app.orbit1UbText.Tooltip = 'Parameter upper bound.';
            app.orbit1UbText.Value = '0.0';
            app.orbit2UbText = uieditfield(app.GridLayout10, 'text');
            app.orbit2UbText.ValueChangedFcn = createCallbackFcn(app, @orbit2UbText_Callback, true);
            app.orbit2UbText.Tag = 'orbit2UbText';
            app.orbit2UbText.Tooltip = 'Parameter upper bound.';
            app.orbit2UbText.Value = '0.0';
            app.orbit3UbText = uieditfield(app.GridLayout10, 'text');
            app.orbit3UbText.ValueChangedFcn = createCallbackFcn(app, @orbit3UbText_Callback, true);
            app.orbit3UbText.Tag = 'orbit3UbText';
            app.orbit3UbText.Tooltip = 'Parameter upper bound.';
            app.orbit3UbText.Value = '0.0';
            app.orbit4UbText = uieditfield(app.GridLayout10, 'text');
            app.orbit4UbText.ValueChangedFcn = createCallbackFcn(app, @orbit4UbText_Callback, true);
            app.orbit4UbText.Tag = 'orbit4UbText';
            app.orbit4UbText.Tooltip = 'Parameter upper bound.';
            app.orbit4UbText.Value = '0.0';
            app.orbit5UbText = uieditfield(app.GridLayout10, 'text');
            app.orbit5UbText.ValueChangedFcn = createCallbackFcn(app, @orbit5UbText_Callback, true);
            app.orbit5UbText.Tag = 'orbit5UbText';
            app.orbit5UbText.Tooltip = 'Parameter upper bound.';
            app.orbit5UbText.Value = '0.0';
            app.orbit6UbText = uieditfield(app.GridLayout10, 'text');
            app.orbit6UbText.ValueChangedFcn = createCallbackFcn(app, @orbit6UbText_Callback, true);
            app.orbit6UbText.Tag = 'orbit6UbText';
            app.orbit6UbText.Tooltip = 'Parameter upper bound.';
            app.orbit6UbText.Value = '0.0';
            app.text2 = uilabel(app.GridLayout3);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Initial State';
            app.epochContextMenu = uicontextmenu(app.lvd_EditInitialStateGUI);
            app.epochContextMenu.Tag = 'epochContextMenu';
            app.enterUTAsDateTimeContextMenu = uimenu(app.epochContextMenu);
            app.enterUTAsDateTimeContextMenu.MenuSelectedFcn = createCallbackFcn(app, @enterUTAsDateTimeContextMenu_Callback, true);
            app.enterUTAsDateTimeContextMenu.Text = 'Enter UT As Date/Time';
            app.enterUTAsDateTimeContextMenu.Tag = 'enterUTAsDateTimeContextMenu';
            % Assign app.epochContextMenu
            app.text3.ContextMenu = app.epochContextMenu;
            app.utText.ContextMenu = app.epochContextMenu;
            app.utUnitLabel.ContextMenu = app.epochContextMenu;
            app.optUtCheckbox.ContextMenu = app.epochContextMenu;
            app.utLbText.ContextMenu = app.epochContextMenu;
            app.utUbText.ContextMenu = app.epochContextMenu;
            app.orbitPanelContextMenu = uicontextmenu(app.lvd_EditInitialStateGUI);
            app.orbitPanelContextMenu.Tag = 'orbitPanelContextMenu';
            app.getOrbitFromSFSFileContextMenu = uimenu(app.orbitPanelContextMenu);
            app.getOrbitFromSFSFileContextMenu.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromSFSFileContextMenu_Callback, true);
            app.getOrbitFromSFSFileContextMenu.Text = 'Get Orbit From SFS File';
            app.getOrbitFromSFSFileContextMenu.Tag = 'getOrbitFromSFSFileContextMenu';
            app.getOrbitFromKSPTOTConnectContextMenu = uimenu(app.orbitPanelContextMenu);
            app.getOrbitFromKSPTOTConnectContextMenu.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromKSPTOTConnectContextMenu_Callback, true);
            app.getOrbitFromKSPTOTConnectContextMenu.Text = 'Get Orbit From KSP';
            app.getOrbitFromKSPTOTConnectContextMenu.Tag = 'getOrbitFromKSPTOTConnectContextMenu';
            app.getOrbitFromKSPActiveVesselMenu = uimenu(app.orbitPanelContextMenu);
            app.getOrbitFromKSPActiveVesselMenu.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromKSPActiveVesselMenu_Callback, true);
            app.getOrbitFromKSPActiveVesselMenu.Text = 'Get Orbit From KSP (Active Vessel)';
            app.getOrbitFromKSPActiveVesselMenu.Tag = 'getOrbitFromKSPActiveVesselMenu';
            app.copyOrbitToClipboardMenu = uimenu(app.orbitPanelContextMenu);
            app.copyOrbitToClipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @copyOrbitToClipboardMenu_Callback, true);
            app.copyOrbitToClipboardMenu.Text = 'Copy Orbit to Clipboard';
            app.copyOrbitToClipboardMenu.Tag = 'copyOrbitToClipboardMenu';
            app.pasteOrbitFromClipboardMenu = uimenu(app.orbitPanelContextMenu);
            app.pasteOrbitFromClipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @pasteOrbitFromClipboardMenu_Callback, true);
            app.pasteOrbitFromClipboardMenu.Text = 'Paste Orbit from Clipboard';
            app.pasteOrbitFromClipboardMenu.Tag = 'pasteOrbitFromClipboardMenu';
            % Assign app.orbitPanelContextMenu
            app.uipanel2.ContextMenu = app.orbitPanelContextMenu;
            app.orbit1Label.ContextMenu = app.orbitPanelContextMenu;
            app.orbit1Text.ContextMenu = app.orbitPanelContextMenu;
            app.orbit1UnitLabel.ContextMenu = app.orbitPanelContextMenu;
            app.optOrbit1Checkbox.ContextMenu = app.orbitPanelContextMenu;
            app.orbit1LbText.ContextMenu = app.orbitPanelContextMenu;
            app.orbit1UbText.ContextMenu = app.orbitPanelContextMenu;
            app.orbit2Label.ContextMenu = app.orbitPanelContextMenu;
            app.orbit2Text.ContextMenu = app.orbitPanelContextMenu;
            app.orbit2UnitLabel.ContextMenu = app.orbitPanelContextMenu;
            app.optOrbit2Checkbox.ContextMenu = app.orbitPanelContextMenu;
            app.orbit2LbText.ContextMenu = app.orbitPanelContextMenu;
            app.orbit2UbText.ContextMenu = app.orbitPanelContextMenu;
            app.orbit3Label.ContextMenu = app.orbitPanelContextMenu;
            app.orbit3Text.ContextMenu = app.orbitPanelContextMenu;
            app.orbit3UnitLabel.ContextMenu = app.orbitPanelContextMenu;
            app.optOrbit3Checkbox.ContextMenu = app.orbitPanelContextMenu;
            app.orbit3LbText.ContextMenu = app.orbitPanelContextMenu;
            app.orbit3UbText.ContextMenu = app.orbitPanelContextMenu;
            app.orbit4Label.ContextMenu = app.orbitPanelContextMenu;
            app.orbit4Text.ContextMenu = app.orbitPanelContextMenu;
            app.orbit4UnitLabel.ContextMenu = app.orbitPanelContextMenu;
            app.optOrbit4Checkbox.ContextMenu = app.orbitPanelContextMenu;
            app.orbit4LbText.ContextMenu = app.orbitPanelContextMenu;
            app.orbit4UbText.ContextMenu = app.orbitPanelContextMenu;
            app.orbit5Label.ContextMenu = app.orbitPanelContextMenu;
            app.orbit5Text.ContextMenu = app.orbitPanelContextMenu;
            app.orbit5UnitLabel.ContextMenu = app.orbitPanelContextMenu;
            app.optOrbit5Checkbox.ContextMenu = app.orbitPanelContextMenu;
            app.orbit5LbText.ContextMenu = app.orbitPanelContextMenu;
            app.orbit5UbText.ContextMenu = app.orbitPanelContextMenu;
            app.orbit6Label.ContextMenu = app.orbitPanelContextMenu;
            app.orbit6Text.ContextMenu = app.orbitPanelContextMenu;
            app.orbit6UnitLabel.ContextMenu = app.orbitPanelContextMenu;
            app.optOrbit6Checkbox.ContextMenu = app.orbitPanelContextMenu;
            app.orbit6LbText.ContextMenu = app.orbitPanelContextMenu;
            app.orbit6UbText.ContextMenu = app.orbitPanelContextMenu;
            % Show the figure after all components are created
            app.lvd_EditInitialStateGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditInitialStateGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditInitialStateGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditInitialStateGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditInitialStateGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditInitialStateGUI)
        end
    end
end