classdef lvd_HaloOrbitConstructor_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HaloOrbitConstructorUIFigure  matlab.ui.Figure
        ComputeHaloOrbitButton        matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        ConstructHaloOrbitTab         matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        DisplayPanel                  matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        ArrivalDeparturePanel         matlab.ui.container.Panel
        DisplayManifoldStateButton    matlab.ui.control.Button
        text18_9                      matlab.ui.control.Label
        PropagationDurationEditField  matlab.ui.control.NumericEditField
        PropagationDurationEditFieldLabel  matlab.ui.control.Label
        ManifoldArriveDepartCombo     matlab.ui.control.DropDown
        ManifoldArriveDepartPointSlider  matlab.ui.control.Slider
        DispAxes                      matlab.ui.control.UIAxes
        HaloOrbitDefintionPanel       matlab.ui.container.Panel
        DisplayPanel_2                matlab.ui.container.Panel
        DisplaySecondaryBodyCheckBox  matlab.ui.control.CheckBox
        DisplayPrimaryBodyCheckBox    matlab.ui.control.CheckBox
        DifferentialCorrectorParametersPanel  matlab.ui.container.Panel
        CoordinateToAdjustDropDown    matlab.ui.control.DropDown
        CoordinateToAdjustDropDownLabel  matlab.ui.control.Label
        StepSizeFactorEditField       matlab.ui.control.NumericEditField
        StepSizeFactorEditFieldLabel  matlab.ui.control.Label
        HaloOrbitPanel                matlab.ui.container.Panel
        text18                        matlab.ui.control.Label
        SenseDropDown                 matlab.ui.control.DropDown
        SenseDropDownLabel            matlab.ui.control.Label
        LibrationPointDropDown        matlab.ui.control.DropDown
        LibrationPointDropDownLabel   matlab.ui.control.Label
        ZAmplitudeEditField           matlab.ui.control.NumericEditField
        ZAmplitudeEditFieldLabel      matlab.ui.control.Label
        CelestialBodiesPanel          matlab.ui.container.Panel
        SecondaryBodyDropDown         matlab.ui.control.DropDown
        SecondaryBodyDropDownLabel    matlab.ui.control.Label
        PrimaryBodyDropDown           matlab.ui.control.DropDown
        PrimaryBodyDropDownLabel      matlab.ui.control.Label
        ResultsTab                    matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        ResultsPanel                  matlab.ui.container.Panel
        text18_8                      matlab.ui.control.Label
        PeriodEditField               matlab.ui.control.NumericEditField
        PeriodEditFieldLabel          matlab.ui.control.Label
        text18_7                      matlab.ui.control.Label
        ZVelocityEditField            matlab.ui.control.NumericEditField
        ZVelocityEditFieldLabel       matlab.ui.control.Label
        text18_6                      matlab.ui.control.Label
        YVelocityEditField            matlab.ui.control.NumericEditField
        YVelocityEditFieldLabel       matlab.ui.control.Label
        text18_5                      matlab.ui.control.Label
        XVelocityEditField            matlab.ui.control.NumericEditField
        XVelocityEditFieldLabel       matlab.ui.control.Label
        text18_4                      matlab.ui.control.Label
        ZPositionEditField            matlab.ui.control.NumericEditField
        ZPositionEditFieldLabel       matlab.ui.control.Label
        text18_3                      matlab.ui.control.Label
        YPositionEditField            matlab.ui.control.NumericEditField
        YPositionEditFieldLabel       matlab.ui.control.Label
        text18_2                      matlab.ui.control.Label
        XPositionEditField            matlab.ui.control.NumericEditField
        XPositionEditFieldLabel       matlab.ui.control.Label
        InstructionsLabel             matlab.ui.control.Label
        text1                         matlab.ui.control.Label
    end


    properties (Access = private)
        celBodyData CelestialBodyData % Description
        hoa HaloOrbitApproximator     % Description
        calcWaitListener % Description
        hWaitBar
        origInstrucStr(:,1) string
    end

    methods (Access = private)
        function updateSecBodyCombo(app)
            bodyInfo = app.PrimaryBodyDropDown.Value;
            [childrenBodyInfo, cbNames] = bodyInfo.getChildrenBodyInfo(app.celBodyData);

            app.SecondaryBodyDropDown.Items = cbNames;
            app.SecondaryBodyDropDown.ItemsData = childrenBodyInfo;
        end

        function updateHOA(app)
            pBodyInfo = app.PrimaryBodyDropDown.Value;
            gm1 = pBodyInfo.gm;
            radius1 = pBodyInfo.radius;

            sBodyInfo = app.SecondaryBodyDropDown.Value;
            gm2 = sBodyInfo.gm;
            sma2 = sBodyInfo.sma;
            radius2 = sBodyInfo.radius;

            Az = app.ZAmplitudeEditField.Value;
            lPt = app.LibrationPointDropDown.Value.name;
            side = app.SenseDropDown.Value.name;

            app.hoa = HaloOrbitApproximator(gm1, gm2, sma2, radius1, radius2, Az, lPt, side);

            app.hoa.diffCorAlpha = app.StepSizeFactorEditField.Value;
            app.hoa.adjustIndEnum = app.CoordinateToAdjustDropDown.Value;

            if(not(isempty(app.calcWaitListener)))
                delete(app.calcWaitListener);
            end

            hClbk = @(h, evt) updateWaitbar(app, h, evt);
            app.calcWaitListener = addlistener(app.hoa,'UpdateCalcWaitbar',hClbk);
        end

        function updateWaitbar(app, ~, evt)
            curVal = evt.Value;
            tolLog10 = evt.PreviousValue;

            if(curVal >= 0)
                app.hWaitBar.Indeterminate = 'on';
            else
                app.hWaitBar.Indeterminate = 'off';

                valWB = 1 - abs((curVal - tolLog10)/tolLog10);
                if(valWB < 0)
                    valWB = 0;
                elseif(valWB > 1)
                    valWB = 1;
                end
                app.hWaitBar.Value = valWB;
            end
        end

        function computeManifold(app)
            tFrac = app.ManifoldArriveDepartPointSlider.Value;
            whichManifold = app.ManifoldArriveDepartCombo.Value;
            propDurDimensioned = app.PropagationDurationEditField.Value;

            app.hoa.computeManifold(tFrac, whichManifold, propDurDimensioned, app.DispAxes);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, celBodyData)
            app.celBodyData = celBodyData;

            centerUIFigure(app.HaloOrbitConstructorUIFigure);
            applySelectedThemeToApp(app);

            bodiesWithChildren = app.celBodyData.getAllBodiesWithChildren();
            bodiesWithChildrenStr = string.empty(1,0);
            for(i=1:length(bodiesWithChildren)) %#ok<*NO4LP>
                bodiesWithChildrenStr(i) = bodiesWithChildren(i).name;
            end
            app.PrimaryBodyDropDown.Items = bodiesWithChildrenStr;
            app.PrimaryBodyDropDown.ItemsData = bodiesWithChildren;

            app.updateSecBodyCombo();

            [listBoxStr, enums] = LibrationPointEnum.getListBoxStr();
            app.LibrationPointDropDown.Items = listBoxStr;
            app.LibrationPointDropDown.ItemsData = enums;

            [listBoxStr, enums] = HaloOrbitSenseEnum.getListBoxStr();
            app.SenseDropDown.Items = listBoxStr;
            app.SenseDropDown.ItemsData = enums;

            [listBoxStr, enums] = HaloCoordAdjustEnum.getListBoxStr();
            app.CoordinateToAdjustDropDown.Items = listBoxStr;
            app.CoordinateToAdjustDropDown.ItemsData = enums;
            app.CoordinateToAdjustDropDown.Value = HaloCoordAdjustEnum.Z;

            [listBoxStr, enums] = HaloArriveDepartManifoldEnum.getListBoxStr();
            app.ManifoldArriveDepartCombo.Items = listBoxStr;
            app.ManifoldArriveDepartCombo.ItemsData = enums;
            app.ManifoldArriveDepartCombo.Value = HaloArriveDepartManifoldEnum.Arrive;

            app.origInstrucStr = string(app.InstructionsLabel.Text);

            app.updateHOA();
        end

        % Value changed function: PrimaryBodyDropDown
        function PrimaryBodyDropDownValueChanged(app, event)
            app.updateSecBodyCombo();
            app.updateHOA();
        end

        % Value changed function: SecondaryBodyDropDown
        function SecondaryBodyDropDownValueChanged(app, event)
            app.updateHOA();
        end

        % Value changed function: LibrationPointDropDown
        function LibrationPointDropDownValueChanged(app, event)
            app.updateHOA();
        end

        % Value changed function: SenseDropDown
        function SenseDropDownValueChanged(app, event)
            app.updateHOA();
        end

        % Value changed function: ZAmplitudeEditField
        function ZAmplitudeEditFieldValueChanged(app, event)
            %             newInput = event.Source.Value;
            %             newInput = attemptStrEval(newInput);
            %             event.Source.Value = newInput;

            app.updateHOA();
        end

        % Button pushed function: ComputeHaloOrbitButton
        function ComputeHaloOrbitButtonPushed(app, event)
            app.updateHOA();
            app.hWaitBar = uiprogressdlg(app.HaloOrbitConstructorUIFigure, ...
                'Message','Computing Halo Orbit...', ...
                'Title','Computing');

            [rVectCR3BP_Prim, vVectCR3BP_Prim, period, approxAzAchieved, corrAzAchieved] = app.hoa.computeCorrectedInitialState(app.DispAxes, app.DisplayPrimaryBodyCheckBox.Value, app.DisplaySecondaryBodyCheckBox.Value);

            if(not(any(isnan(rVectCR3BP_Prim))) && not(any(isnan(vVectCR3BP_Prim))))
                app.XPositionEditField.Value = rVectCR3BP_Prim(1);
                app.YPositionEditField.Value = rVectCR3BP_Prim(2);
                app.ZPositionEditField.Value = rVectCR3BP_Prim(3);
                app.XVelocityEditField.Value = vVectCR3BP_Prim(1);
                app.YVelocityEditField.Value = vVectCR3BP_Prim(2);
                app.ZVelocityEditField.Value = vVectCR3BP_Prim(3);
                app.PeriodEditField.Value = period;

                validSol = true;
            else
                validSol = false;
            end

            pBodyInfo = app.PrimaryBodyDropDown.Value;
            sBodyInfo = app.SecondaryBodyDropDown.Value;
            str = strrep(app.origInstrucStr, '<BODY1>', pBodyInfo.name);
            str = strrep(str, '<BODY2>', sBodyInfo.name);
            app.InstructionsLabel.Text = str;

            app.computeManifold();

            close(app.hWaitBar);
            app.hWaitBar = [];

            app.DispAxes.Toolbar.reset();
            app.InstructionsLabel.Visible = 'on';

            if(validSol)
                str = sprintf('Halo orbit calculations were successful. \n\nApprox Z-Amp Achieved: %0.3f km \nCorrected Z-Amp Achieved: %0.3f km\n', approxAzAchieved, corrAzAchieved);
                uialert(app.HaloOrbitConstructorUIFigure,str,'Calculations Complete', 'Icon','success');
            else
                str = sprintf('Halo orbit calculations were unsuccessful.  Disregard contents of Results tab. \n\nApprox Z-Amp Achieved: %0.3f km \nCorrected Z-Amp Achieved: %0.3f km\n', approxAzAchieved, corrAzAchieved);
                uialert(app.HaloOrbitConstructorUIFigure,str,'Calculations Complete', 'Icon','error');
            end
        end

        % Value changed function: StepSizeFactorEditField
        function StepSizeFactorEditFieldValueChanged(app, event)
            %             newInput = event.Source.Value;
            %             newInput = attemptStrEval(newInput);
            %             event.Source.Value = newInput;

            app.updateHOA();
        end

        % Value changed function: CoordinateToAdjustDropDown
        function CoordinateToAdjustDropDownValueChanged(app, event)
            app.updateHOA();
        end

        % Value changing function: ManifoldArriveDepartPointSlider
        function ManifoldArriveDepartPointSliderValueChanging(app, event)
            app.ManifoldArriveDepartPointSlider.Value = event.Value;
            app.computeManifold();
        end

        % Value changed function: ManifoldArriveDepartCombo
        function ManifoldArriveDepartComboValueChanged(app, event)
            app.computeManifold();
        end

        % Value changed function: PropagationDurationEditField
        function PropagationDurationEditFieldValueChanged(app, event)
            %             newInput = event.Source.Value;
            %             newInput = attemptStrEval(newInput);
            %             event.Source.Value = newInput;

            app.computeManifold();
        end

        % Value changed function: ManifoldArriveDepartPointSlider
        function ManifoldArriveDepartPointSliderValueChanged(app, event)
            app.computeManifold();
        end

        % Button pushed function: DisplayManifoldStateButton
        function DisplayManifoldStateButtonPushed(app, event)
            rVect = app.hoa.rVectCR3BP_Prim_Manifold;
            vVect = app.hoa.vVectCR3BP_Prim_Manifold;

            if(not(any(isnan(rVect))) && not(any(isnan(vVect))))
                app.XPositionEditField.Value = rVect(1);
                app.YPositionEditField.Value = rVect(2);
                app.ZPositionEditField.Value = rVect(3);
                app.XVelocityEditField.Value = vVect(1);
                app.YVelocityEditField.Value = vVect(2);
                app.ZVelocityEditField.Value = vVect(3);

                uialert(app.HaloOrbitConstructorUIFigure, 'Results tab state updated!','Results Updated','Icon','success');
            else
                uialert(app.HaloOrbitConstructorUIFigure, 'There was an error getting the halo orbit arrival/departure manifold state.  Please try again.','Error','Icon','error');
            end

        end

        % Key press function: HaloOrbitConstructorUIFigure
        function HaloOrbitConstructorUIFigureKeyPress(app, event)

        end

        % Window key press function: HaloOrbitConstructorUIFigure
        function HaloOrbitConstructorUIFigureWindowKeyPress(app, event)
            if(app.HaloOrbitConstructorUIFigure.CurrentObject == app.ManifoldArriveDepartPointSlider)
                limits = app.ManifoldArriveDepartPointSlider.Limits;
                maxLim = max(limits);
                minLim = min(limits);

                if(length(event.Modifier) == 1 && strcmpi(event.Modifier{1}, 'control'))
                    stepSize = (maxLim-minLim)/100;
                    
                elseif(length(event.Modifier) == 2 && strcmpi(event.Modifier{1}, 'shift') && strcmpi(event.Modifier{2}, 'control'))
                    stepSize = (maxLim-minLim)/1000;
                    
                else
                    stepSize = (maxLim-minLim)/10;
                end

                value = app.ManifoldArriveDepartPointSlider.Value;
                switch event.Key
                    case 'rightarrow'
                        value = value + stepSize;

                    case 'leftarrow'
                        value = value - stepSize;
                       
                    otherwise
                        return;
                end
                
                if(value > maxLim)
                     value = maxLim;
                elseif(value < minLim)
                    value = minLim;
                end
                app.ManifoldArriveDepartPointSlider.Value = value;

                app.computeManifold();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HaloOrbitConstructorUIFigure = uifigure('Visible', 'off');
            app.HaloOrbitConstructorUIFigure.Name = 'Halo Orbit Constructor';
            app.HaloOrbitConstructorUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.HaloOrbitConstructorUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @HaloOrbitConstructorUIFigureWindowKeyPress, true);
            app.HaloOrbitConstructorUIFigure.KeyPressFcn = createCallbackFcn(app, @HaloOrbitConstructorUIFigureKeyPress, true);
            app.text1 = uilabel(app.HaloOrbitConstructorUIFigure);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Halo Orbit Constructor';
            app.TabGroup = uitabgroup(app.HaloOrbitConstructorUIFigure);
            app.ConstructHaloOrbitTab = uitab(app.TabGroup);
            app.ConstructHaloOrbitTab.Title = 'Construct Halo Orbit';
            app.GridLayout = uigridlayout(app.ConstructHaloOrbitTab);
            app.HaloOrbitDefintionPanel = uipanel(app.GridLayout);
            app.HaloOrbitDefintionPanel.Title = 'Halo Orbit Defintion';
            app.CelestialBodiesPanel = uipanel(app.HaloOrbitDefintionPanel);
            app.CelestialBodiesPanel.Title = 'Celestial Bodies';
            app.PrimaryBodyDropDownLabel = uilabel(app.CelestialBodiesPanel);
            app.PrimaryBodyDropDownLabel.Text = 'Primary Body';
            app.PrimaryBodyDropDown = uidropdown(app.CelestialBodiesPanel);
            app.PrimaryBodyDropDown.ValueChangedFcn = createCallbackFcn(app, @PrimaryBodyDropDownValueChanged, true);
            app.PrimaryBodyDropDown.Tag = 'primaryBodyCombo';
            app.SecondaryBodyDropDownLabel = uilabel(app.CelestialBodiesPanel);
            app.SecondaryBodyDropDownLabel.Text = 'Secondary Body';
            app.SecondaryBodyDropDown = uidropdown(app.CelestialBodiesPanel);
            app.SecondaryBodyDropDown.ValueChangedFcn = createCallbackFcn(app, @SecondaryBodyDropDownValueChanged, true);
            app.SecondaryBodyDropDown.Tag = 'secondaryBodyCombo';
            app.HaloOrbitPanel = uipanel(app.HaloOrbitDefintionPanel);
            app.HaloOrbitPanel.Title = 'Halo Orbit';
            app.ZAmplitudeEditFieldLabel = uilabel(app.HaloOrbitPanel);
            app.ZAmplitudeEditFieldLabel.Text = 'Z Amplitude';
            app.ZAmplitudeEditField = uieditfield(app.HaloOrbitPanel, 'numeric');
            app.ZAmplitudeEditField.Limits = [0 Inf];
            app.ZAmplitudeEditField.ValueDisplayFormat = '%11.4f';
            app.ZAmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @ZAmplitudeEditFieldValueChanged, true);
            app.ZAmplitudeEditField.Tooltip = {'The approximate size of the halo orbit in the out of plane (Z) direction.  This is only approximate!  You will need to play with this number to get the orbit you desire.  This number must be greater than 0.0 km.  Note that there is an upper limit to this number that is a function of the two bodies selected.  If your orbits fail to converge, try reducing this number.'};
            app.LibrationPointDropDownLabel = uilabel(app.HaloOrbitPanel);
            app.LibrationPointDropDownLabel.Text = 'Libration Point';
            app.LibrationPointDropDown = uidropdown(app.HaloOrbitPanel);
            app.LibrationPointDropDown.ValueChangedFcn = createCallbackFcn(app, @LibrationPointDropDownValueChanged, true);
            app.LibrationPointDropDown.Tooltip = {'The L1 point is between the primary and secondary bodies.  The L2 point is beyond the secondary body.'};
            app.SenseDropDownLabel = uilabel(app.HaloOrbitPanel);
            app.SenseDropDownLabel.Text = 'Sense';
            app.SenseDropDown = uidropdown(app.HaloOrbitPanel);
            app.SenseDropDown.ValueChangedFcn = createCallbackFcn(app, @SenseDropDownValueChanged, true);
            app.SenseDropDown.Tooltip = {'Determines which side of the plane the periapsis of the orbit should be in.'};
            app.text18 = uilabel(app.HaloOrbitPanel);
            app.text18.Tag = 'text18';
            app.text18.Text = 'km';
            app.DifferentialCorrectorParametersPanel = uipanel(app.HaloOrbitDefintionPanel);
            app.DifferentialCorrectorParametersPanel.Title = 'Differential Corrector Parameters';
            app.StepSizeFactorEditFieldLabel = uilabel(app.DifferentialCorrectorParametersPanel);
            app.StepSizeFactorEditFieldLabel.Text = 'Step Size Factor';
            app.StepSizeFactorEditField = uieditfield(app.DifferentialCorrectorParametersPanel, 'numeric');
            app.StepSizeFactorEditField.Limits = [1e-05 1];
            app.StepSizeFactorEditField.ValueDisplayFormat = '%11.5g';
            app.StepSizeFactorEditField.ValueChangedFcn = createCallbackFcn(app, @StepSizeFactorEditFieldValueChanged, true);
            app.StepSizeFactorEditField.Tooltip = {'The step size taken by the differential corrector when correcting the approximated halo orbit will be reduced by this factor.  Must be between 1E-5 and 1.0.  Decreasing causes increased run times but potentially more stable optimization.'};
            app.StepSizeFactorEditField.Value = 0.1;
            app.CoordinateToAdjustDropDownLabel = uilabel(app.DifferentialCorrectorParametersPanel);
            app.CoordinateToAdjustDropDownLabel.Text = 'Coordinate To Adjust';
            app.CoordinateToAdjustDropDown = uidropdown(app.DifferentialCorrectorParametersPanel);
            app.CoordinateToAdjustDropDown.ValueChangedFcn = createCallbackFcn(app, @CoordinateToAdjustDropDownValueChanged, true);
            app.CoordinateToAdjustDropDown.Tooltip = {'Select X_0 adjust the initial X position and Z_0 to adjust the initial Z position in the differential corrector.  If you can''t get a halo orbit to converge with one of these, try the other.'};
            app.DisplayPanel_2 = uipanel(app.HaloOrbitDefintionPanel);
            app.DisplayPanel_2.Title = 'Display';
            app.DisplayPrimaryBodyCheckBox = uicheckbox(app.DisplayPanel_2);
            app.DisplayPrimaryBodyCheckBox.Text = 'Display Primary Body';
            app.DisplaySecondaryBodyCheckBox = uicheckbox(app.DisplayPanel_2);
            app.DisplaySecondaryBodyCheckBox.Text = 'Display Secondary Body';
            app.DisplaySecondaryBodyCheckBox.Value = true;
            app.DisplayPanel = uipanel(app.GridLayout);
            app.DisplayPanel.Title = 'Display';
            app.GridLayout2 = uigridlayout(app.DisplayPanel);
            app.DispAxes = uiaxes(app.GridLayout2);
            xlabel(app.DispAxes, 'X')
            ylabel(app.DispAxes, 'Y')
            zlabel(app.DispAxes, 'Z')
            app.ArrivalDeparturePanel = uipanel(app.GridLayout2);
            app.ArrivalDeparturePanel.Title = 'Arrival/Departure';
            app.ManifoldArriveDepartPointSlider = uislider(app.ArrivalDeparturePanel);
            app.ManifoldArriveDepartPointSlider.Limits = [0 1];
            app.ManifoldArriveDepartPointSlider.ValueChangedFcn = createCallbackFcn(app, @ManifoldArriveDepartPointSliderValueChanged, true);
            app.ManifoldArriveDepartPointSlider.ValueChangingFcn = createCallbackFcn(app, @ManifoldArriveDepartPointSliderValueChanging, true);
            app.ManifoldArriveDepartPointSlider.Tooltip = {'The fraction of the halo orbit period at which orbit arrival or insertion occurs.  You can think of this as "where in the halo orbit" the arrival or departure occurs. '; ''; 'Use left and right arrows to step, and hold down control for fine stepping.'};
            app.ManifoldArriveDepartCombo = uidropdown(app.ArrivalDeparturePanel);
            app.ManifoldArriveDepartCombo.ValueChangedFcn = createCallbackFcn(app, @ManifoldArriveDepartComboValueChanged, true);
            app.ManifoldArriveDepartCombo.Tooltip = {'Whether to compute an arrival or departure trajectory to or from the last computed halo orbit. '};
            app.PropagationDurationEditFieldLabel = uilabel(app.ArrivalDeparturePanel);
            app.PropagationDurationEditFieldLabel.Text = 'Propagation Duration';
            app.PropagationDurationEditField = uieditfield(app.ArrivalDeparturePanel, 'numeric');
            app.PropagationDurationEditField.Limits = [0 Inf];
            app.PropagationDurationEditField.ValueDisplayFormat = '%11.3f';
            app.PropagationDurationEditField.ValueChangedFcn = createCallbackFcn(app, @PropagationDurationEditFieldValueChanged, true);
            app.PropagationDurationEditField.Tooltip = {'How long to propagate out the arrival or departure orbits.  Arrival orbits are plotted backwards in time, ending at the halo orbit.  Departure orbits are plotted forwards in time starting at the halo orbit.'};
            app.text18_9 = uilabel(app.ArrivalDeparturePanel);
            app.text18_9.Tag = 'text18';
            app.text18_9.Text = 'sec';
            app.DisplayManifoldStateButton = uibutton(app.ArrivalDeparturePanel, 'push');
            app.DisplayManifoldStateButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayManifoldStateButtonPushed, true);
            app.DisplayManifoldStateButton.Tooltip = {'Overwrites the state in the Results tab with the halo orbit arrival or departure state. '};
            app.DisplayManifoldStateButton.Text = 'Display Manifold State';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.GridLayout3 = uigridlayout(app.ResultsTab);
            app.InstructionsLabel = uilabel(app.GridLayout3);
            app.InstructionsLabel.Visible = 'off';
            app.InstructionsLabel.Text = {'The position and velocity values shown to the left are relative to the <BODY1> - <BODY2> two body rotating reference frame with the origin at the primary body. '; ''; 'To use these values, create a Cartesian state in the above described two body rotating frame, either in the Initial State or Set Kinematic State action.'; ''; 'Additional optimization will be needed to create the halo orbit.  To do this:'; '   1) Propagate out the orbit as shown by one period''s amount of time;'; '   2) Create X, Y, and Z position vector and velocity vector constraints (for six in total) that relative to the above mentioned two body rotating frame such that the state of the spacecraft before and after propagating is the same;'; '   3) Create an Impulsive Delta-V action immediately after setting the state.  Allow the three delta-v vector components to vary (+/- 10 m/s is generally sufficient).'; '   4) Allow the duration of the propagation to vary (+/- 10% should be sufficient).'; '   5) Optimize the mission.'; ''; 'See lvdExample_L2HaloOrbit.mat and lvdExample_L1HaloOrbit.mat for examples of this process.'};
            app.ResultsPanel = uipanel(app.GridLayout3);
            app.ResultsPanel.Title = 'Results';
            app.XPositionEditFieldLabel = uilabel(app.ResultsPanel);
            app.XPositionEditFieldLabel.Text = 'X Position';
            app.XPositionEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.XPositionEditField.ValueDisplayFormat = '%11.10f';
            app.XPositionEditField.Editable = 'off';
            app.text18_2 = uilabel(app.ResultsPanel);
            app.text18_2.Tag = 'text18';
            app.text18_2.Text = 'km';
            app.YPositionEditFieldLabel = uilabel(app.ResultsPanel);
            app.YPositionEditFieldLabel.Text = 'Y Position';
            app.YPositionEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.YPositionEditField.ValueDisplayFormat = '%11.10f';
            app.YPositionEditField.Editable = 'off';
            app.text18_3 = uilabel(app.ResultsPanel);
            app.text18_3.Tag = 'text18';
            app.text18_3.Text = 'km';
            app.ZPositionEditFieldLabel = uilabel(app.ResultsPanel);
            app.ZPositionEditFieldLabel.Text = 'Z Position';
            app.ZPositionEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.ZPositionEditField.ValueDisplayFormat = '%11.10f';
            app.ZPositionEditField.Editable = 'off';
            app.text18_4 = uilabel(app.ResultsPanel);
            app.text18_4.Tag = 'text18';
            app.text18_4.Text = 'km';
            app.XVelocityEditFieldLabel = uilabel(app.ResultsPanel);
            app.XVelocityEditFieldLabel.Text = 'X Velocity';
            app.XVelocityEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.XVelocityEditField.ValueDisplayFormat = '%11.10f';
            app.XVelocityEditField.Editable = 'off';
            app.text18_5 = uilabel(app.ResultsPanel);
            app.text18_5.Tag = 'text18';
            app.text18_5.Text = 'km/s';
            app.YVelocityEditFieldLabel = uilabel(app.ResultsPanel);
            app.YVelocityEditFieldLabel.Text = 'Y Velocity';
            app.YVelocityEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.YVelocityEditField.ValueDisplayFormat = '%11.10f';
            app.YVelocityEditField.Editable = 'off';
            app.text18_6 = uilabel(app.ResultsPanel);
            app.text18_6.Tag = 'text18';
            app.text18_6.Text = 'km/s';
            app.ZVelocityEditFieldLabel = uilabel(app.ResultsPanel);
            app.ZVelocityEditFieldLabel.Text = 'Z Velocity';
            app.ZVelocityEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.ZVelocityEditField.ValueDisplayFormat = '%11.10f';
            app.ZVelocityEditField.Editable = 'off';
            app.text18_7 = uilabel(app.ResultsPanel);
            app.text18_7.Tag = 'text18';
            app.text18_7.Text = 'km/s';
            app.PeriodEditFieldLabel = uilabel(app.ResultsPanel);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.PeriodEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.PeriodEditField.ValueDisplayFormat = '%11.3f';
            app.PeriodEditField.Editable = 'off';
            app.text18_8 = uilabel(app.ResultsPanel);
            app.text18_8.Tag = 'text18';
            app.text18_8.Text = 'sec';
            app.ComputeHaloOrbitButton = uibutton(app.HaloOrbitConstructorUIFigure, 'push');
            app.ComputeHaloOrbitButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeHaloOrbitButtonPushed, true);
            app.ComputeHaloOrbitButton.Icon = 'orbit(1).png';
            app.ComputeHaloOrbitButton.Text = 'Compute Halo Orbit';
            % Show the figure after all components are created
            app.HaloOrbitConstructorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_HaloOrbitConstructor_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.HaloOrbitConstructorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.HaloOrbitConstructorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HaloOrbitConstructorUIFigure)
        end
    end
end