classdef lvd_MissionNotesGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_MissionNotesGUI  matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        text2                matlab.ui.control.Label
        MissionNotesPanel    matlab.ui.container.Panel
        GridLayout2          matlab.ui.container.GridLayout
        missionNotesText     matlab.ui.control.TextArea
        saveCloseButton      matlab.ui.control.Button
    end


    methods (Access = private)
        function setupGui(app, handles)
            lvdData = getappdata(handles.lvd_MissionNotesGUI, 'lvdData');
            set(handles.missionNotesText,'String',lvdData.notes);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_MissionNotesGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_MissionNotesGUI (see VARARGIN)

            % Choose default command line output for lvd_MissionNotesGUI
            handles.output = hObject;

            centerUIFigure(app.lvd_MissionNotesGUI);
            applySelectedThemeToApp(app);

            % Update handles structure
            guidata(hObject, handles);
            setappdata(hObject, 'lvdData', varargin{1});
            guidata(hObject, handles);

            setupGui(app, handles);
        end

        % Button pushed function: saveCloseButton
        function saveCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_MissionNotesGUI, 'lvdData');
            lvdData.notes = get(handles.missionNotesText,'String');

            close(handles.lvd_MissionNotesGUI);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_MissionNotesGUI = uifigure('Visible', 'off');
            app.lvd_MissionNotesGUI.Name = 'Mission Notes';
            app.lvd_MissionNotesGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_MissionNotesGUI.Tag = 'lvd_MissionNotesGUI';
            app.GridLayout = uigridlayout(app.lvd_MissionNotesGUI);
            app.saveCloseButton = uibutton(app.GridLayout, 'push');
            app.saveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveCloseButton_Callback, true);
            app.saveCloseButton.Tag = 'saveCloseButton';
            app.saveCloseButton.Icon = 'save-file.png';
            app.saveCloseButton.Text = 'Save & Close';
            app.MissionNotesPanel = uipanel(app.GridLayout);
            app.MissionNotesPanel.Title = 'Mission Notes';
            app.GridLayout2 = uigridlayout(app.MissionNotesPanel);
            app.missionNotesText = uitextarea(app.GridLayout2);
            app.missionNotesText.Tag = 'missionNotesText';
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Mission Notes';
            % Show the figure after all components are created
            app.lvd_MissionNotesGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_MissionNotesGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_MissionNotesGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_MissionNotesGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_MissionNotesGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_MissionNotesGUI)
        end
    end
end