classdef lvd_runCaseMatrix_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RunCaseMatrixUIFigure      matlab.ui.Figure
        GridLayout8                matlab.ui.container.GridLayout
        Panel_2                    matlab.ui.container.Panel
        GridLayout9                matlab.ui.container.GridLayout
        OpenResultsButton          matlab.ui.control.Button
        RunTimeCounterLabel        matlab.ui.control.Label
        CancelButton               matlab.ui.control.Button
        StatusLabel                matlab.ui.control.Label
        SpinnerIcon                matlab.ui.control.Image
        GridLayout                 matlab.ui.container.GridLayout
        Panel                      matlab.ui.container.Panel
        PanelGrid                  matlab.ui.container.GridLayout
        CaseMatrixTabs             matlab.ui.container.TabGroup
        ParamsTab                  matlab.ui.container.Tab
        ParamsGrid                 matlab.ui.container.GridLayout
        ParamHelpLabel             matlab.ui.control.Label
        SelectedParamTable         matlab.ui.control.Table
        ParamButtonGrid            matlab.ui.container.GridLayout
        VariationTypeDropdown      matlab.ui.control.DropDown
        VariationTypeLabel         matlab.ui.control.Label
        RemoveParamButton          matlab.ui.control.Button
        AddParamButton             matlab.ui.control.Button
        AvailableParamListbox      matlab.ui.control.ListBox
        ParamSearchText            matlab.ui.control.EditField
        SamplingTab                matlab.ui.container.Tab
        SamplingGrid               matlab.ui.container.GridLayout
        NumCasesLabel              matlab.ui.control.Label
        RunLocationSelector        wt.FileSelector
        OutputLocationLabel        matlab.ui.control.Label
        MaxRunAttemptsText         matlab.ui.control.EditField
        MaxRunAttemptsLabel        matlab.ui.control.Label
        NumOfWorkersText           matlab.ui.control.EditField
        NumberofWorkersLabel       matlab.ui.control.Label
        RunModeDropdown            matlab.ui.control.DropDown
        RunModeLabel               matlab.ui.control.Label
        SeedSubGrid                matlab.ui.container.GridLayout
        RandomSeedCheckbox         matlab.ui.control.CheckBox
        RandomizeSeedButton        matlab.ui.control.Button
        SeedText                   matlab.ui.control.EditField
        SeedLabel                  matlab.ui.control.Label
        NumSamplesText             matlab.ui.control.EditField
        NumSamplesLabel            matlab.ui.control.Label
        SamplingModeDropdown       matlab.ui.control.DropDown
        SamplingModeLabel          matlab.ui.control.Label
        ResponsesTab               matlab.ui.container.Tab
        RespGrid                   matlab.ui.container.GridLayout
        SelectedRespTable          matlab.ui.control.Table
        RespButtonGrid             matlab.ui.container.GridLayout
        RemoveRespButton           matlab.ui.control.Button
        AddRespButton              matlab.ui.control.Button
        RespEventBodyGrid          matlab.ui.container.GridLayout
        RespFrameSelector          referenceFrameSelectComp
        RespEventDropdown          matlab.ui.control.DropDown
        RespNodeDropdown           matlab.ui.control.DropDown
        RespTaskListbox            matlab.ui.control.ListBox
        RespSearchText             matlab.ui.control.EditField
        OutputTab                  matlab.ui.container.Tab
        OutputGrid                 matlab.ui.container.GridLayout
        PersistCaseFilesCheckbox   matlab.ui.control.CheckBox
        WriteGaTimeSeriesCheckbox  matlab.ui.control.CheckBox
        WriteCsvCheckbox           matlab.ui.control.CheckBox
        WriteMatCheckbox           matlab.ui.control.CheckBox
        WriteXlsxCheckbox          matlab.ui.control.CheckBox
        StatusTab                  matlab.ui.container.Tab
        StatusTabGrid              matlab.ui.container.GridLayout
        RunStatusTable             matlab.ui.control.Table
        RunCaseMatrixButton        matlab.ui.control.Button
        text2                      matlab.ui.control.Label
    end

    properties (Access = private)
        lvdData LvdData

        caseMatrix LvdCaseMatrix

        %The run definition this window edits.  It is lvdData's own object and
        %not a copy, so a twenty parameter trade study is still there the next
        %time the window opens, and is saved with the mission.
        setup LvdSweepSetup

        %Every parameter this mission offers, in factory order.  The search
        %box filters what the listbox shows, so a listbox position is not an
        %index into this array -- the mapping is carried in ItemsData.
        availParams(1,:) AbstractLvdSweepParameter = AbstractLvdSweepParameter.empty(1,0);

        %The graphical analysis quantities a response may read, unfiltered.
        respTaskStrs(1,:) cell = {};

        %What the last run produced, so Open Results does not have to go back
        %to disk for something that is already in memory.
        lastResults LvdSweepResults

        runStartClock(1,1) = 0;
    end

    methods (Access = private)
        function populateGUI(app)
            %Everything this window shows comes out of the mission or out of
            %the setup hanging off it; the window itself holds no state of its
            %own.  There is deliberately no "at least one plugin variable"
            %gate any more: a mission with no plugin variables can still sweep
            %an optimization variable, a constraint bound or a vehicle knob.
            app.availParams = LvdSweepParameterFactory.enumerate(app.lvdData);

            app.VariationTypeDropdown.Items = app.getVariationTypeNames();

            app.SamplingModeDropdown.Items = LvdSweepSamplingEnum.getListBoxStr();
            app.SamplingModeDropdown.Value = app.setup.samplingMode.name;

            app.RunModeDropdown.Items = LvdCaseMatrixRunModeEnum.getListBoxStr();
            app.RunModeDropdown.Value = app.setup.runMode.name;

            app.RespNodeDropdown.Items = LvdSweepResponseNodeEnum.getListBoxStr();
            app.respTaskStrs = lvd_getGraphAnalysisTaskList(app.lvdData, getLvdGAExcludeList());

            app.populateRespEventDropdown();
            app.initializeRespFrameSelector();

            app.refreshAvailableParamListbox();
            app.refreshSelectedParamTable();
            app.refreshRespTaskListbox();
            app.refreshSelectedRespTable();

            app.NumSamplesText.Value = fullAccNum2Str(app.setup.numSamples);
            app.SeedText.Value = fullAccNum2Str(app.setup.seed);
            app.RandomSeedCheckbox.Value = app.setup.randomizeSeedEachRun;
            app.updateSeedEnables();

            %A setup that has never run has no output folder, and one saved on
            %another machine may name a folder that is not there any more.
            if(isempty(app.setup.outputLocation) || not(isfolder(app.setup.outputLocation)))
                app.setup.outputLocation = pwd;
            end
            app.RunLocationSelector.Value = app.setup.outputLocation;

            %A stored worker count of 1 is the class default rather than a
            %considered choice, so fall back on the machine (or the pool that
            %is already up) the way this window always has.
            if(app.setup.numWorkers <= 1)
                pp = gcp('nocreate');
                if(not(isempty(pp)))
                    app.setup.numWorkers = pp.NumWorkers;
                else
                    app.setup.numWorkers = feature('numCores');
                end
            end
            app.NumOfWorkersText.Value = fullAccNum2Str(app.setup.numWorkers);
            app.MaxRunAttemptsText.Value = fullAccNum2Str(app.setup.maxNumAttempts);

            app.WriteXlsxCheckbox.Value = app.setup.writeXlsx;
            app.WriteMatCheckbox.Value = app.setup.writeMat;
            app.WriteCsvCheckbox.Value = app.setup.writeCsv;
            app.WriteGaTimeSeriesCheckbox.Value = app.setup.writeGaTimeSeries;
            app.PersistCaseFilesCheckbox.Value = app.setup.persistCaseFiles;

            app.refreshCaseCountLabel();
            app.handleSamplingEnables();

            app.SpinnerIcon.Visible = false;
            app.CancelButton.Visible = false;
            app.StatusLabel.Text = 'Ready';
            app.RunTimeCounterLabel.Text = '';
            app.RunTimeCounterLabel.Visible = false;
            app.OpenResultsButton.Enable = false;
        end

        function names = getVariationTypeNames(~)
            %A grid and the three distributions in one dropdown: to the user
            %"how does this parameter vary" is a single question, even though
            %a grid and a distribution are different classes underneath.
            dists = AbstractLvdDistribution.getAllDistributionTypes();

            names = cell(1, numel(dists) + 1);
            names{1} = 'Grid';

            for(i=1:numel(dists)) %#ok<*NO4LP>
                names{i+1} = dists(i).getTypeName();
            end
        end

        function initializeRespFrameSelector(app)
            app.RespFrameSelector.initializeWithFrames(app.lvdData);
            initBody = app.lvdData.initStateModel.centralBody;
            if(not(isempty(initBody)))
                app.RespFrameSelector.setSelectedFrame(initBody.getBodyCenteredInertialFrame());
            end
        end

        function populateRespEventDropdown(app)
            %Whole mission, or one event.  The scope is stored as an event
            %NUMBER rather than a handle, so a response still means "event 3"
            %in the byte stream clone each case actually runs on.
            numEvts = app.lvdData.script.getTotalNumOfEvents();

            items = cell(1, numEvts + 1);
            items{1} = 'Whole Mission';

            for(i=1:numEvts)
                items{i+1} = sprintf('Event %u: %s', i, app.lvdData.script.getEventForInd(i).name);
            end

            app.RespEventDropdown.Items = items;
            app.RespEventDropdown.ItemsData = 0:numEvts;
            app.RespEventDropdown.Value = 0;
        end

        function refreshAvailableParamListbox(app)
            %One flat list with the group carried in the label, filtered by
            %the search box.  ItemsData holds each row's index into
            %availParams, because filtering breaks the correspondence between
            %a listbox position and the full list.
            filter = lower(strtrim(app.ParamSearchText.Value));

            [groupNames, indsByGroup] = LvdSweepParameterFactory.groupParameters(app.availParams);

            items = {};
            data = [];

            for(g=1:numel(groupNames))
                inds = indsByGroup{g};

                for(k=1:numel(inds))
                    label = sprintf('%s | %s', groupNames{g}, app.availParams(inds(k)).getFullLabel());

                    if(not(isempty(filter)) && not(contains(lower(label), filter)))
                        continue;
                    end

                    items{end+1} = label; %#ok<AGROW>
                    data(end+1) = inds(k); %#ok<AGROW>
                end
            end

            if(isempty(items))
                app.AvailableParamListbox.Items = {};
                app.AvailableParamListbox.ItemsData = [];
            else
                app.AvailableParamListbox.Items = items;
                app.AvailableParamListbox.ItemsData = data;
            end

            if(isempty(app.availParams))
                app.ParamHelpLabel.Text = ['This mission has nothing to sweep over yet.  Add a plugin variable, ', ...
                                           'enable an optimization variable, or add a constraint, and reopen this window.'];
            else
                app.ParamHelpLabel.Text = sprintf(['%u of %u parameters shown.  Select one or more and press Add; then set ', ...
                                                   'how each one varies in the table on the right.  A grid uses lower bound, ', ...
                                                   'upper bound and step; a distribution uses its own two or three parameters.'], ...
                                                  numel(items), numel(app.availParams));
            end
        end

        function refreshSelectedParamTable(app)
            %The last three columns mean different things depending on how the
            %row varies, which is why they are named for both readings rather
            %than being relabelled as the selection moves.
            n = app.setup.getNumParameters();

            data = cell(n, 6);

            for(i=1:n)
                param = app.setup.params(i);
                variation = app.setup.variations(i);

                data{i,1} = param.getFullLabel();
                data{i,2} = param.getGroupName();
                data{i,3} = app.getTypeNameForVariation(variation);

                v = app.getNumbersForVariation(variation);

                for(k=1:3)
                    if(k <= numel(v))
                        data{i,3+k} = fullAccNum2Str(v(k));
                    else
                        data{i,3+k} = '';
                    end
                end
            end

            app.SelectedParamTable.Data = data;
            app.refreshCaseCountLabel();
        end

        function name = getTypeNameForVariation(~, variation)
            if(isa(variation, 'LvdSweepGridVariation'))
                name = 'Grid';
            else
                name = variation.dist.getTypeName();
            end
        end

        function v = getNumbersForVariation(~, variation)
            if(isa(variation, 'LvdSweepGridVariation'))
                v = [variation.lowerBnd, variation.upperBnd, variation.step];
            else
                v = variation.dist.getParamValues();
            end
        end

        function variation = makeVariationOfType(~, param, typeName)
            %A freshly added parameter is sized off its own current value, so
            %the table never starts at 0 to 1 for a number that is three and a
            %half million.
            if(strcmp(typeName, 'Grid'))
                [lb, ub] = param.getSuggestedBounds();

                step = (ub - lb)/4;
                if(step <= 0 || not(isfinite(step)))
                    step = 1;
                end

                variation = LvdSweepGridVariation(lb, ub, step);
            else
                dist = AbstractLvdDistribution.createByTypeName(typeName, param.getCurrentValue());
                variation = LvdSweepDistVariation(dist);
            end
        end

        function ind = getSelectedParamRow(app)
            %The row the variation controls act on.  A table with no explicit
            %selection acts on the last row, which is the one the user just
            %added.
            ind = [];

            if(app.setup.getNumParameters() < 1)
                return;
            end

            sel = app.SelectedParamTable.Selection;

            if(isempty(sel))
                ind = app.setup.getNumParameters();
            else
                ind = min(sel(:,1));
            end
        end

        function refreshRespTaskListbox(app)
            filter = lower(strtrim(app.RespSearchText.Value));

            items = app.respTaskStrs;

            if(not(isempty(filter)))
                items = items(contains(lower(items), filter));
            end

            if(isempty(items))
                app.RespTaskListbox.Items = {};
            else
                app.RespTaskListbox.Items = items;
            end
        end

        function refreshSelectedRespTable(app)
            n = app.setup.getNumResponses();

            data = cell(n, 1);

            for(i=1:n)
                data{i,1} = app.setup.responses(i).getName();
            end

            app.SelectedRespTable.Data = data;
            app.refreshCaseCountLabel();
        end

        function refreshCaseCountLabel(app)
            %The number a user actually needs before pressing Run: a full
            %factorial over five three-level parameters is 243 cases, and it
            %is much better to learn that here than thirty minutes in.
            numCases = app.setup.getNumCases();

            app.NumCasesLabel.Text = sprintf('%u case(s) will run, harvesting %u response(s) from each.', ...
                                             numCases, app.setup.getNumResponses());
        end

        function handleSamplingEnables(app)
            %A full factorial run takes its case count from the grid levels
            %themselves, so for it a sample count and a seed would be
            %decoration.
            usesN = app.setup.samplingMode.usesNumSamples();

            app.NumSamplesText.Enable = usesN;
            app.SeedText.Enable = usesN && not(app.setup.randomizeSeedEachRun);
            app.RandomizeSeedButton.Enable = usesN && not(app.setup.randomizeSeedEachRun);
        end

        function setSetupControlsEnabled(app, tf)
            %What the two panels' Enable properties used to do for free.  A tab
            %group has no Enable, and disabling whole tabs would grey out the
            %status table the user is watching, so the editing controls are
            %disabled individually and the Status tab stays live.
            comps = {app.AvailableParamListbox, app.ParamSearchText, app.AddParamButton, ...
                     app.RemoveParamButton, app.VariationTypeDropdown, app.SelectedParamTable, ...
                     app.SamplingModeDropdown, app.NumSamplesText, app.SeedText, ...
                     app.RandomizeSeedButton, app.RandomSeedCheckbox, app.RunModeDropdown, app.RunLocationSelector, ...
                     app.NumOfWorkersText, app.MaxRunAttemptsText, ...
                     app.RespSearchText, app.RespTaskListbox, app.RespNodeDropdown, ...
                     app.RespEventDropdown, app.AddRespButton, ...
                     app.RemoveRespButton, app.SelectedRespTable, ...
                     app.WriteXlsxCheckbox, app.WriteMatCheckbox, app.WriteCsvCheckbox, ...
                     app.WriteGaTimeSeriesCheckbox, app.PersistCaseFilesCheckbox};

            if(tf)
                enableState = 'on';
            else
                enableState = 'off';
            end

            for(i=1:numel(comps))
                comps{i}.Enable = enableState;
            end

            if(tf)
                app.handleSamplingEnables();
            end
        end

        function [value, ok] = readNumberField(app, hField, numberName, lb, ub, isInt, prevValue)
            %The read-validate-or-put-it-back dance every numeric field in
            %this window used to spell out for itself.
            newInput = attemptStrEval(hField.Value);
            hField.Value = newInput;

            value = str2double(newInput);
            errMsg = validateNumber(value, numberName, lb, ub, isInt, {}, newInput);

            ok = isempty(errMsg);

            if(not(ok))
                value = prevValue;
                hField.Value = fullAccNum2Str(prevValue);

                uialert(app.RunCaseMatrixUIFigure, errMsg, 'Input Error', 'Icon','error');
            end
        end

        function handleTaskStatusChange(app, ~, evt)
            caseNum = evt.task.caseNumber;

            sStatus = evt.updatedData;
            oldStatus = sStatus.oldStatus;
            updatedStatus = sStatus.newStatus;

            data = app.RunStatusTable.Data;
            caseNums = cell2mat(data(:,1));
            bool = caseNum == caseNums;

            statusName = updatedStatus.name;
            data{bool, end-1} = statusName;
            app.RunStatusTable.Data = data;

            s1 = uistyle("Icon", updatedStatus.icon);
            s2 = uistyle("IconAlignment",'leftmargin');

            targetCellRow = find(bool, 1, 'first');
            targetCellCol = width(data) - 1;
            targetCell = [targetCellRow, targetCellCol];

            addStyle(app.RunStatusTable, s1, 'cell', targetCell);
            addStyle(app.RunStatusTable, s2, 'cell', targetCell);

            d = datetime();
            dStr = datestr(d, 'HH:MM:SS PM');
            if(oldStatus ~= updatedStatus)
                statusLabel = sprintf('(%s) Case %u status updated: %s -> %s', dStr, caseNum, oldStatus.name, statusName);
            else
                statusLabel = sprintf('(%s) Case %u status updated: %s', dStr, caseNum, statusName);
            end
            app.StatusLabel.Text = statusLabel;

            drawnow;
        end

        function handleTaskOutputMessageChange(app, ~, evt)
            caseNum = evt.task.caseNumber;
            updatedMessage = evt.updatedData;

            data = app.RunStatusTable.Data;
            caseNums = cell2mat(data(:,1));
            bool = caseNum == caseNums;

            data{bool, end} = updatedMessage;
            app.RunStatusTable.Data = data;

            drawnow;
        end

        function updateRuntimeDurationLabel(app, ~,~)
            t = toc(app.runStartClock);

            s = seconds(t);
            s.Format = "hh:mm:ss";

            app.RunTimeCounterLabel.Text = string(s);
            drawnow;
        end

        function genCaseMatrixProgressBarUpdate(app, src, evt, hProg)
            hProg.Value = evt.taskNum / evt.totalTasks;
        end

        function runCaseMatrix(app)
            app.caseMatrix.maxNumAttempts = app.setup.maxNumAttempts;

            app.setSetupControlsEnabled(false);
            app.CaseMatrixTabs.SelectedTab = app.StatusTab;

            tableData = app.caseMatrix.getUITableData();
            app.RunStatusTable.Data = tableData;
            app.RunStatusTable.ColumnSortable = true;
            app.RunStatusTable.ColumnWidth = [repmat("fit",1,width(tableData)-1), "auto"];
            app.RunStatusTable.ColumnFormat = repmat({'char'},1,width(tableData));

            s1 = uistyle('HorizontalAlignment','center');
            s2 = uistyle("Icon","none");
            addStyle(app.RunStatusTable,s1);
            addStyle(app.RunStatusTable,s2);

            tasks = app.caseMatrix.tasks;
            for(i=1:length(tasks))
                task = tasks(i);

                callback = @(src, evt) app.handleTaskStatusChange(src, evt);
                addlistener(task,'StatusUpdated',callback);

                callback = @(src, evt) app.handleTaskOutputMessageChange(src, evt);
                addlistener(task,'OutputMessageUpdated',callback);

                sStatus.oldStatus = task.status;
                sStatus.newStatus = task.status;
                data = LvdCaseMatrixTaskNotifyEventData(task, sStatus);
                app.handleTaskStatusChange(task, data);
            end
            drawnow;

            app.SpinnerIcon.Visible = true;
            app.CancelButton.Visible = true;
            app.CancelButton.Enable = true;
            app.RunTimeCounterLabel.Text = '00:00:00';
            app.RunTimeCounterLabel.Visible = true;

            app.runStartClock = tic;
            timerFcn = @(src, evt) app.updateRuntimeDurationLabel(src, evt);
            tmr = timer("TimerFcn",timerFcn, "Period",0.5, "ExecutionMode","fixedRate");
            start(tmr);

            try
                app.caseMatrix.runAllTasks(@(s) app.onProgressMessage(s));
            catch ME
                beep;
                warning(ME.message);
            end

            stop(tmr);
            delete(tmr);

            app.SpinnerIcon.Visible = false;
            app.CancelButton.Visible = false;
            app.CancelButton.Enable = true;

            app.RunTimeCounterLabel.Text = '';
            app.RunTimeCounterLabel.Visible = false;

            t = toc(app.runStartClock);
            s = seconds(t);
            s.Format = "hh:mm:ss";
            app.StatusLabel.Text = sprintf('Run finished in %s.  Ready.', string(s));

            app.setSetupControlsEnabled(true);

            app.runStartClock = 0;

            %A cancelled or wholly failed run still has a results object; it
            %is the one thing that says which cases failed and why, so the
            %viewer is offered either way.
            app.lastResults = app.caseMatrix.results;
            app.OpenResultsButton.Enable = not(isempty(app.lastResults));

            app.RunCaseMatrixButton.Enable = "on";
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, lvdData)
            app.lvdData = lvdData;
            app.setup = lvdData.caseMatrixSetup;
            centerUIFigure(app.RunCaseMatrixUIFigure);
            applySelectedThemeToApp(app);
            app.populateGUI();
        end

        % Button pushed function: RunCaseMatrixButton
        function RunCaseMatrixButtonPushed(app, event)
            [ok, msg] = app.setup.validate(app.lvdData);
            if(not(ok))
                uialert(app.RunCaseMatrixUIFigure, msg, 'Invalid Sweep Setup', 'Icon','error');
                return;
            end
            app.setup.outputLocation = app.RunLocationSelector.FullPath;
            if(not(isfolder(app.setup.outputLocation)))
                uialert(app.RunCaseMatrixUIFigure, 'The selected output folder does not exist or is an invalid path.', 'Invalid Output Location', 'Icon','error');
                return;
            end
            app.RunCaseMatrixButton.Enable = 'off';
            app.StatusLabel.Text = 'Building cases, please wait...';
            app.SpinnerIcon.Visible = true;
            drawnow;
            writeOutput = @(varargin) disp('');
            startParallelPool(app.RunCaseMatrixUIFigure, writeOutput, app.setup.numWorkers);
            app.caseMatrix = LvdCaseMatrix(app.lvdData, app.setup.outputLocation);
            app.caseMatrix.runName = app.setup.runName;
            if(app.setup.randomizeSeedEachRun)
                app.setup.seed = LvdSweepSampler.getRandomSeed();
                app.SeedText.Value = fullAccNum2Str(app.setup.seed);
            end

            app.caseMatrix.createTasksFromSetup(app.setup);
            app.RunStatusTable.ColumnName = app.caseMatrix.getStatusTableColumns();
            app.runCaseMatrix();
        end

        % Value changed function: NumOfWorkersText
        function NumOfWorkersTextValueChanged(app, event)
            [value, ok] = app.readNumberField(app.NumOfWorkersText, 'Number of Parallel Workers', 1, 2*feature('numCores'), true, app.setup.numWorkers);
            if(ok)
                app.setup.numWorkers = round(value);
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CancelButton.Enable = false;
            drawnow;
            if(not(isempty(app.caseMatrix)))
                app.caseMatrix.cancelRun();
            end
            app.setSetupControlsEnabled(true);
            app.CancelButton.Enable = true;
            drawnow;
        end

        % Value changed function: MaxRunAttemptsText
        function MaxRunAttemptsTextValueChanged(app, event)
            [value, ok] = app.readNumberField(app.MaxRunAttemptsText, 'Max Number of Run Attempts', 1, Inf, true, app.setup.maxNumAttempts);
            if(ok)
                app.setup.maxNumAttempts = round(value);
            end
        end

        % Value changed function: ParamSearchText
        function ParamSearchTextValueChanged(app, event)
            app.refreshAvailableParamListbox();
        end

        % Button pushed function: AddParamButton
        function AddParamButtonPushed(app, event)
%Value is in ItemsData space: the indices into availParams.
            sel = app.AvailableParamListbox.Value;
            if(isempty(sel))
                return;
            end
            for(k=1:numel(sel))
                ind = sel(k);
                if(ind < 1 || ind > numel(app.availParams))
                    continue;
                end
                param = app.availParams(ind);
                if(any(app.setup.params == param))
                    continue;
                end
                app.setup.addParameter(param, app.makeVariationOfType(param, 'Grid'));
            end
            app.refreshSelectedParamTable();
        end

        % Button pushed function: RemoveParamButton
        function RemoveParamButtonPushed(app, event)
            sel = app.SelectedParamTable.Selection;
            if(isempty(sel))
                return;
            end
            rows = unique(sel(:,1), 'sorted');
            for(k=numel(rows):-1:1)
                app.setup.removeParameterAtInd(rows(k));
            end
            app.refreshSelectedParamTable();
        end

        % Value changed function: VariationTypeDropdown
        function VariationTypeDropdownValueChanged(app, event)
            ind = app.getSelectedParamRow();
            if(isempty(ind))
                return;
            end
            param = app.setup.params(ind);
            app.setup.setVariationAtInd(ind, app.makeVariationOfType(param, app.VariationTypeDropdown.Value));
            app.refreshSelectedParamTable();
        end

        % Cell edit function: SelectedParamTable
        function SelectedParamTableCellEdit(app, event)
            r = event.Indices(1);
            c = event.Indices(2);
            if(r >= 1 && r <= app.setup.getNumParameters() && c >= 4 && c <= 6)
                variation = app.setup.variations(r);
                newVal = str2double(attemptStrEval(event.NewData));
                if(isfinite(newVal))
                    if(isa(variation, 'LvdSweepGridVariation'))
                        v = [variation.lowerBnd, variation.upperBnd, variation.step];
                        v(c-3) = newVal;
                        variation.lowerBnd = v(1);
                        variation.upperBnd = v(2);
                        variation.step = v(3);
                    else
                        v = variation.dist.getParamValues();
                        if((c-3) <= numel(v))
                            v(c-3) = newVal;
                            try
                                variation.dist.setParamValues(v);
                            catch
                            end
                        end
                    end
                end
            end
            app.refreshSelectedParamTable();
        end

        % Value changed function: SamplingModeDropdown
        function SamplingModeDropdownValueChanged(app, event)
            [enum, ~] = LvdSweepSamplingEnum.getEnumForListboxStr(app.SamplingModeDropdown.Value);
            app.setup.samplingMode = enum;
            app.handleSamplingEnables();
            app.refreshCaseCountLabel();
        end

        % Value changed function: NumSamplesText
        function NumSamplesTextValueChanged(app, event)
            [value, ok] = app.readNumberField(app.NumSamplesText, 'Number of Samples', 1, Inf, true, app.setup.numSamples);
            if(ok)
                app.setup.numSamples = round(value);
            end
            app.refreshCaseCountLabel();
        end

        % Value changed function: SeedText
        function SeedTextValueChanged(app, event)
            [value, ok] = app.readNumberField(app.SeedText, 'Seed', 0, 2^31-1, true, app.setup.seed);
            if(ok)
                app.setup.seed = value;
            end
        end

        % Button pushed function: RandomizeSeedButton
        function RandomizeSeedButtonPushed(app, event)
            app.setup.seed = LvdSweepSampler.getRandomSeed();
            app.SeedText.Value = fullAccNum2Str(app.setup.seed);
            app.RandomSeedCheckbox.Value = app.setup.randomizeSeedEachRun;
            app.updateSeedEnables();
        end

        % Value changed function: RunModeDropdown
        function RunModeDropdownValueChanged(app, event)
            [enum, ~] = LvdCaseMatrixRunModeEnum.getEnumForListboxStr(app.RunModeDropdown.Value);
            app.setup.runMode = enum;
            app.refreshCaseCountLabel();
        end

        % Value changed function: RespSearchText
        function RespSearchTextValueChanged(app, event)
            app.refreshRespTaskListbox();
        end

        % Button pushed function: AddRespButton
        function AddRespButtonPushed(app, event)
            sel = app.RespTaskListbox.Value;
            if(isempty(sel))
                return;
            end
            [nodeEnum, ~] = LvdSweepResponseNodeEnum.getEnumForListboxStr(app.RespNodeDropdown.Value);
            eventNum = app.RespEventDropdown.Value;
            frame = app.RespFrameSelector.getSelectedFrame();
            for(k=1:numel(sel))
                task = GraphicalAnalysisTask(sel{k}, frame);
                app.setup.addResponse(LvdSweepResponse(task, nodeEnum, eventNum));
            end
            app.refreshSelectedRespTable();
        end

        % Button pushed function: RemoveRespButton
        function RemoveRespButtonPushed(app, event)
            sel = app.SelectedRespTable.Selection;
            if(isempty(sel))
                return;
            end
            rows = unique(sel(:,1), 'sorted');
            for(k=numel(rows):-1:1)
                app.setup.removeResponseAtInd(rows(k));
            end
            app.refreshSelectedRespTable();
        end

        % Value changed function: WriteXlsxCheckbox
        function WriteXlsxCheckboxValueChanged(app, event)
            app.setup.writeXlsx = logical(app.WriteXlsxCheckbox.Value);
        end

        % Value changed function: WriteMatCheckbox
        function WriteMatCheckboxValueChanged(app, event)
            app.setup.writeMat = logical(app.WriteMatCheckbox.Value);
        end

        % Value changed function: WriteCsvCheckbox
        function WriteCsvCheckboxValueChanged(app, event)
            app.setup.writeCsv = logical(app.WriteCsvCheckbox.Value);
        end

        % Value changed function: WriteGaTimeSeriesCheckbox
        function WriteGaTimeSeriesCheckboxValueChanged(app, event)
            app.setup.writeGaTimeSeries = logical(app.WriteGaTimeSeriesCheckbox.Value);
        end

        % Value changed function: PersistCaseFilesCheckbox
        function PersistCaseFilesCheckboxValueChanged(app, event)
            app.setup.persistCaseFiles = logical(app.PersistCaseFilesCheckbox.Value);
        end

        % Button pushed function: OpenResultsButton
        function OpenResultsButtonPushed(app, event)
            if(isempty(app.lastResults))
                return;
            end
            lvd_SweepResultsGUI_App(app.lastResults);
        end

        % Live progress function: onProgressMessage
        function onProgressMessage(app, event)
            %One live optimizer report: refreshes that case's
            %Iter/Objective/MaxViol/Optimality cells.  Runs on the
            %client via the run's DataQueue; guarded so a late message
            %after a rebuild cannot throw inside the queue callback.
            try
                if(isempty(app.caseMatrix) || isempty(app.caseMatrix.tasks))
                    return;
                end

                ids = [app.caseMatrix.tasks.id];
                r = find(ids == s.taskId, 1, 'first');
                if(isempty(r))
                    return;
                end

                data = app.RunStatusTable.Data;
                if(r > size(data, 1))
                    return;
                end

                nP = app.setup.getNumParameters();
                data{r, 2+nP} = s.iter;
                data{r, 3+nP} = s.fval;
                data{r, 4+nP} = s.viol;

                if(isnan(s.optim))
                    data{r, 5+nP} = '';
                else
                    data{r, 5+nP} = sprintf('%.3g', s.optim);
                end

                app.RunStatusTable.Data = data;
                drawnow('limitrate');
            catch
            end
        end

        % Value changed function: RandomSeedCheckbox
        function RandomSeedCheckboxValueChanged(app, event)
            app.setup.randomizeSeedEachRun = logical(app.RandomSeedCheckbox.Value);
            app.updateSeedEnables();
        end

        % Helper function: updateSeedEnables
        function updateSeedEnables(app, event)
            %Sampling mode and the randomize flag jointly decide the
            %seed controls; handleSamplingEnables already honors both.
            app.handleSamplingEnables();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RunCaseMatrixUIFigure = uifigure('Visible', 'off');
            app.RunCaseMatrixUIFigure.Name = 'Run Case Matrix';
            app.RunCaseMatrixUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout8 = uigridlayout(app.RunCaseMatrixUIFigure);
            app.GridLayout = uigridlayout(app.GridLayout8);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Run Case Matrix';
            app.Panel = uipanel(app.GridLayout);
            app.PanelGrid = uigridlayout(app.Panel);
            app.CaseMatrixTabs = uitabgroup(app.PanelGrid);
            app.ParamsTab = uitab(app.CaseMatrixTabs);
            app.ParamsTab.Title = 'Parameters';
            app.ParamsGrid = uigridlayout(app.ParamsTab);
            app.ParamSearchText = uieditfield(app.ParamsGrid, 'text');
            app.ParamSearchText.ValueChangedFcn = createCallbackFcn(app, @ParamSearchTextValueChanged, true);
            app.ParamSearchText.Tooltip = {'Filters the available parameters below.  Searches the group and the parameter name.'};
            app.AvailableParamListbox = uilistbox(app.ParamsGrid);
            app.AvailableParamListbox.Multiselect = 'on';
            app.AvailableParamListbox.Tooltip = {'Everything in the mission that can be swept: plugin variables, optimization variable elements, constraint bounds and vehicle knobs.'};
            app.AvailableParamListbox.DoubleClickedFcn = createCallbackFcn(app, @AddParamButtonPushed, true);
            app.AvailableParamListbox.Tooltip = {'Double-click adds the parameter, like Add >>.'};
            app.ParamButtonGrid = uigridlayout(app.ParamsGrid);
            app.AddParamButton = uibutton(app.ParamButtonGrid, 'push');
            app.AddParamButton.ButtonPushedFcn = createCallbackFcn(app, @AddParamButtonPushed, true);
            app.AddParamButton.Tooltip = {'Sweeps the selected available parameters; set how each one varies in the table on the right.'};
            app.AddParamButton.Text = 'Add >>';
            app.RemoveParamButton = uibutton(app.ParamButtonGrid, 'push');
            app.RemoveParamButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveParamButtonPushed, true);
            app.RemoveParamButton.Tooltip = {'Removes the selected swept-parameter rows.'};
            app.RemoveParamButton.Text = '<< Remove';
            app.VariationTypeLabel = uilabel(app.ParamButtonGrid);
            app.VariationTypeLabel.Text = 'Variation';
            app.VariationTypeDropdown = uidropdown(app.ParamButtonGrid);
            app.VariationTypeDropdown.ValueChangedFcn = createCallbackFcn(app, @VariationTypeDropdownValueChanged, true);
            app.VariationTypeDropdown.Tooltip = {'How the selected parameter row varies: a grid of explicit levels, or one of the sampling distributions.'};
            app.SelectedParamTable = uitable(app.ParamsGrid);
            app.SelectedParamTable.ColumnName = {'Parameter', 'Kind', 'Variation', 'A', 'B', 'C'};
            app.SelectedParamTable.ColumnEditable = [false, false, false, true, true, true];
            app.SelectedParamTable.CellEditCallback = createCallbackFcn(app, @SelectedParamTableCellEdit, true);
            app.SelectedParamTable.Tooltip = {'One row per swept parameter.  A, B, C are the grid bounds and step, or the distribution parameters.  Select a row, then pick its variation type below.'};
            app.ParamHelpLabel = uilabel(app.ParamsGrid);
            app.SamplingTab = uitab(app.CaseMatrixTabs);
            app.SamplingTab.Title = 'Sampling and Run';
            app.SamplingGrid = uigridlayout(app.SamplingTab);
            app.SamplingModeLabel = uilabel(app.SamplingGrid);
            app.SamplingModeLabel.Text = 'Sampling';
            app.SamplingModeDropdown = uidropdown(app.SamplingGrid);
            app.SamplingModeDropdown.ValueChangedFcn = createCallbackFcn(app, @SamplingModeDropdownValueChanged, true);
            app.SamplingModeDropdown.Tooltip = {'Full Factorial crosses every level of every grid parameter.  Latin Hypercube and Random draw N samples; every run is reproducible from its seed.'};
            app.NumSamplesLabel = uilabel(app.SamplingGrid);
            app.NumSamplesLabel.Text = 'Num Samples';
            app.NumSamplesText = uieditfield(app.SamplingGrid, 'text');
            app.NumSamplesText.ValueChangedFcn = createCallbackFcn(app, @NumSamplesTextValueChanged, true);
            app.NumSamplesText.Tooltip = {'Number of cases to run (sampled modes).'};
            app.SeedLabel = uilabel(app.SamplingGrid);
            app.SeedLabel.Text = 'Seed';
            app.SeedSubGrid = uigridlayout(app.SamplingGrid);
            app.SeedText = uieditfield(app.SeedSubGrid, 'text');
            app.SeedText.ValueChangedFcn = createCallbackFcn(app, @SeedTextValueChanged, true);
            app.SeedText.Tooltip = {'Seed for the sampler.  A run is reproducible from its seed alone.'};
            app.RandomizeSeedButton = uibutton(app.SeedSubGrid, 'push');
            app.RandomizeSeedButton.ButtonPushedFcn = createCallbackFcn(app, @RandomizeSeedButtonPushed, true);
            app.RandomizeSeedButton.Tooltip = {'Draws a fresh random seed into the Seed field.'};
            app.RandomizeSeedButton.Text = 'Randomize';
            app.RandomSeedCheckbox = uicheckbox(app.SeedSubGrid);
            app.RandomSeedCheckbox.ValueChangedFcn = createCallbackFcn(app, @RandomSeedCheckboxValueChanged, true);
            app.RandomSeedCheckbox.Tooltip = {'Draw a fresh seed at every run start (recorded in the results, so the run stays reproducible).  Off uses the Seed field.'};
            app.RandomSeedCheckbox.Text = 'Random each run';
            app.RunModeLabel = uilabel(app.SamplingGrid);
            app.RunModeLabel.Text = 'Run Mode';
            app.RunModeDropdown = uidropdown(app.SamplingGrid);
            app.RunModeDropdown.ValueChangedFcn = createCallbackFcn(app, @RunModeDropdownValueChanged, true);
            app.RunModeDropdown.Tooltip = {'Optimize runs the optimizer on every case.  Propagate Only surveys the design space without optimizing.'};
            app.NumberofWorkersLabel = uilabel(app.SamplingGrid);
            app.NumberofWorkersLabel.Text = 'Number of Workers';
            app.NumOfWorkersText = uieditfield(app.SamplingGrid, 'text');
            app.NumOfWorkersText.ValueChangedFcn = createCallbackFcn(app, @NumOfWorkersTextValueChanged, true);
            app.NumOfWorkersText.Tooltip = {'Parallel workers used to run the cases.  Optimize mode requires a pool.'};
            app.MaxRunAttemptsLabel = uilabel(app.SamplingGrid);
            app.MaxRunAttemptsLabel.Text = 'Max Run Attempts';
            app.MaxRunAttemptsText = uieditfield(app.SamplingGrid, 'text');
            app.MaxRunAttemptsText.ValueChangedFcn = createCallbackFcn(app, @MaxRunAttemptsTextValueChanged, true);
            app.MaxRunAttemptsText.Tooltip = {'Per-case retry budget.  Failed cases are retried after a full pass.'};
            app.OutputLocationLabel = uilabel(app.SamplingGrid);
            app.OutputLocationLabel.Text = 'Output Location';
            app.RunLocationSelector = wt.FileSelector(app.SamplingGrid);
            app.RunLocationSelector.SelectionType = 'folder';
            app.NumCasesLabel = uilabel(app.SamplingGrid);
            app.ResponsesTab = uitab(app.CaseMatrixTabs);
            app.ResponsesTab.Title = 'Responses';
            app.RespGrid = uigridlayout(app.ResponsesTab);
            app.RespSearchText = uieditfield(app.RespGrid, 'text');
            app.RespSearchText.ValueChangedFcn = createCallbackFcn(app, @RespSearchTextValueChanged, true);
            app.RespSearchText.Tooltip = {'Filters the graphical analysis quantities below.'};
            app.RespTaskListbox = uilistbox(app.RespGrid);
            app.RespTaskListbox.Multiselect = 'on';
            app.RespTaskListbox.DoubleClickedFcn = createCallbackFcn(app, @AddRespButtonPushed, true);
            app.RespTaskListbox.Tooltip = {'Double-click adds the response, like Add >>.'};
            app.RespNodeDropdown = uidropdown(app.RespGrid);
            app.RespNodeDropdown.Tooltip = {'Where in the scoped span the response is read: an end state, or the minimum, maximum or mean over the span.'};
            app.RespEventBodyGrid = uigridlayout(app.RespGrid);
            app.RespEventDropdown = uidropdown(app.RespEventBodyGrid);
            app.RespEventDropdown.Tooltip = {'Whole mission, or one event.'};
            app.RespFrameSelector = referenceFrameSelectComp(app.RespEventBodyGrid);
            app.RespButtonGrid = uigridlayout(app.RespGrid);
            app.AddRespButton = uibutton(app.RespButtonGrid, 'push');
            app.AddRespButton.ButtonPushedFcn = createCallbackFcn(app, @AddRespButtonPushed, true);
            app.AddRespButton.Tooltip = {'Harvests the selected quantities (at the chosen node and scope) from every case.'};
            app.AddRespButton.Text = 'Add >>';
            app.RemoveRespButton = uibutton(app.RespButtonGrid, 'push');
            app.RemoveRespButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRespButtonPushed, true);
            app.RemoveRespButton.Tooltip = {'Removes the selected response rows.'};
            app.RemoveRespButton.Text = '<< Remove';
            app.SelectedRespTable = uitable(app.RespGrid);
            app.SelectedRespTable.ColumnName = {'Response'};
            app.SelectedRespTable.Tooltip = {'One row per harvested response.  Select rows and press << Remove to drop them.'};
            app.OutputTab = uitab(app.CaseMatrixTabs);
            app.OutputTab.Title = 'Output';
            app.OutputGrid = uigridlayout(app.OutputTab);
            app.WriteXlsxCheckbox = uicheckbox(app.OutputGrid);
            app.WriteXlsxCheckbox.ValueChangedFcn = createCallbackFcn(app, @WriteXlsxCheckboxValueChanged, true);
            app.WriteXlsxCheckbox.Text = 'Excel workbook (.xlsx) with the results and run info';
            app.WriteXlsxCheckbox.Tooltip = {'Excel workbook (.xlsx) with the results and run info.'};
            app.WriteMatCheckbox = uicheckbox(app.OutputGrid);
            app.WriteMatCheckbox.ValueChangedFcn = createCallbackFcn(app, @WriteMatCheckboxValueChanged, true);
            app.WriteMatCheckbox.Text = 'MAT file (.mat) with the results object the viewer loads';
            app.WriteMatCheckbox.Tooltip = {'MAT file (.mat) with the results object the viewer loads.'};
            app.WriteCsvCheckbox = uicheckbox(app.OutputGrid);
            app.WriteCsvCheckbox.ValueChangedFcn = createCallbackFcn(app, @WriteCsvCheckboxValueChanged, true);
            app.WriteCsvCheckbox.Text = 'CSV file (.csv) with the results table';
            app.WriteCsvCheckbox.Tooltip = {'CSV file (.csv) with the results table.'};
            app.WriteGaTimeSeriesCheckbox = uicheckbox(app.OutputGrid);
            app.WriteGaTimeSeriesCheckbox.ValueChangedFcn = createCallbackFcn(app, @WriteGaTimeSeriesCheckboxValueChanged, true);
            app.WriteGaTimeSeriesCheckbox.Tooltip = {'One sheet per case with every graphical analysis quantity over time.  Off by default: at large case counts it dominates the run.'};
            app.WriteGaTimeSeriesCheckbox.Text = 'Per-case graphical analysis time series';
            app.PersistCaseFilesCheckbox = uicheckbox(app.OutputGrid);
            app.PersistCaseFilesCheckbox.ValueChangedFcn = createCallbackFcn(app, @PersistCaseFilesCheckboxValueChanged, true);
            app.PersistCaseFilesCheckbox.Tooltip = {'One .mat per case.  Required in Optimize mode (the warm start reads them); optional in Propagate Only mode.'};
            app.PersistCaseFilesCheckbox.Text = 'One .mat file per case';
            app.StatusTab = uitab(app.CaseMatrixTabs);
            app.StatusTab.Title = 'Status';
            app.StatusTabGrid = uigridlayout(app.StatusTab);
            app.RunCaseMatrixButton = uibutton(app.StatusTabGrid, 'push');
            app.RunCaseMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @RunCaseMatrixButtonPushed, true);
            app.RunCaseMatrixButton.Icon = 'gear_flow_chart.png';
            app.RunCaseMatrixButton.Tooltip = {'Builds and runs every case of the sweep.'};
            app.RunCaseMatrixButton.Text = 'Run Case Matrix';
            app.RunStatusTable = uitable(app.StatusTabGrid);
            app.RunStatusTable.ColumnName = {'Case'; 'Parameters'; 'Status'; 'Message'};
            app.RunStatusTable.Tooltip = {'One row per case: inputs, live optimizer progress in Optimize mode, and the final status.'};
            app.Panel_2 = uipanel(app.GridLayout8);
            app.GridLayout9 = uigridlayout(app.Panel_2);
            app.SpinnerIcon = uiimage(app.GridLayout9);
            app.SpinnerIcon.ImageSource = 'Spin-1s-200px_transparentBg.gif';
            app.StatusLabel = uilabel(app.GridLayout9);
            app.StatusLabel.Text = 'Ready';
            app.CancelButton = uibutton(app.GridLayout9, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Icon = 'cancel.png';
            app.CancelButton.Tooltip = {'Cancel the current case matrix run.'};
            app.CancelButton.Text = 'Cancel';
            app.RunTimeCounterLabel = uilabel(app.GridLayout9);
            app.RunTimeCounterLabel.Tooltip = {'Current case matrix run time (HH:MM:SS).'};
            app.RunTimeCounterLabel.Text = '00:00:00';
            app.OpenResultsButton = uibutton(app.GridLayout9, 'push');
            app.OpenResultsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenResultsButtonPushed, true);
            app.OpenResultsButton.Tooltip = {'Opens the results of the last run in the sweep results viewer.'};
            app.OpenResultsButton.Text = 'Open Results...';
            app.OpenResultsButton.Enable = 'off';
            % Show the figure after all components are created
            app.RunCaseMatrixUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_runCaseMatrix_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RunCaseMatrixUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.RunCaseMatrixUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RunCaseMatrixUIFigure)
        end
    end
end