classdef ma_LvdMainGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ma_LvdMainGUI                   matlab.ui.Figure
        fileMenu                        matlab.ui.container.Menu
        newMissionPlanMenu              matlab.ui.container.Menu
        openMissionPlanMenu             matlab.ui.container.Menu
        saveMissionPlanMenu             matlab.ui.container.Menu
        saveAsMissionPlanMenu           matlab.ui.container.Menu
        NewMissionPlanfromMFMSOutputMenu  matlab.ui.container.Menu
        openRecentMissionPlansMenu      matlab.ui.container.Menu
        exitMenu                        matlab.ui.container.Menu
        editMenu                        matlab.ui.container.Menu
        undoMenu                        matlab.ui.container.Menu
        redoMenu                        matlab.ui.container.Menu
        editMissionNotesMenu            matlab.ui.container.Menu
        viewMenu                        matlab.ui.container.Menu
        editViewSettingsMenu            matlab.ui.container.Menu
        setActiveViewProfileMenu        matlab.ui.container.Menu
        popOutOrbitDisplayMenu          matlab.ui.container.Menu
        scenarioMenu                    matlab.ui.container.Menu
        editLaunchVehicleMenu           matlab.ui.container.Menu
        editInitialStateMenu            matlab.ui.container.Menu
        editStopwatchesMenu             matlab.ui.container.Menu
        editExtremaMenu                 matlab.ui.container.Menu
        editCalculusObjectsMenu         matlab.ui.container.Menu
        editGroundObjsMenu              matlab.ui.container.Menu
        SensorsMenu                     matlab.ui.container.Menu
        EditSensorsMenu                 matlab.ui.container.Menu
        EditSensorTargetsMenu           matlab.ui.container.Menu
        SensorReportsMenu               matlab.ui.container.Menu
        geometryMenu                    matlab.ui.container.Menu
        editPointsMenu                  matlab.ui.container.Menu
        editVectorsMenu                 matlab.ui.container.Menu
        editAnglesMenu                  matlab.ui.container.Menu
        editPlanesMenu                  matlab.ui.container.Menu
        editCoordSysMenu                matlab.ui.container.Menu
        editRefRamesMenu                matlab.ui.container.Menu
        simulationMenu                  matlab.ui.container.Menu
        runScriptMenu                   matlab.ui.container.Menu
        createkOSExecCodeMenu           matlab.ui.container.Menu
        RunCaseMatrixMenu               matlab.ui.container.Menu
        optimizationMenu                matlab.ui.container.Menu
        editObjFunctionMenu             matlab.ui.container.Menu
        editConstraintsMenu             matlab.ui.container.Menu
        perturbOptVarsMenu              matlab.ui.container.Menu
        adjustVariablesMenu             matlab.ui.container.Menu
        showConstrJacobianHeatMapMenu   matlab.ui.container.Menu
        ScaleContraintsbyMenu           matlab.ui.container.Menu
        ScaleConstraintsCurrentValueMenu  matlab.ui.container.Menu
        ScaleConstraintsJacobianMenu    matlab.ui.container.Menu
        optimizeMissionMenu             matlab.ui.container.Menu
        pluginsMenu                     matlab.ui.container.Menu
        managePluginsMenu               matlab.ui.container.Menu
        PluginVariablesMenu             matlab.ui.container.Menu
        settingsMenu                    matlab.ui.container.Menu
        integrationSettingsMenu         matlab.ui.container.Menu
        intMinAltitudeMenu              matlab.ui.container.Menu
        intMaxSimTimeMenu               matlab.ui.container.Menu
        maxScriptPropTimeMenu           matlab.ui.container.Menu
        sparseIntegratorOutputMenu      matlab.ui.container.Menu
        setIntegrationStepSizeForAllEventsMenu  matlab.ui.container.Menu
        optimSettingsMenu               matlab.ui.container.Menu
        selectOptimizationAlgosMenu     matlab.ui.container.Menu
        autopropagateMenu               matlab.ui.container.Menu
        displayEvtPropTimesInLogFileMenu  matlab.ui.container.Menu
        configureCelestialBodyStateCacheMenu  matlab.ui.container.Menu
        toolsMenu                       matlab.ui.container.Menu
        astroCalculatorsMenu            matlab.ui.container.Menu
        haloOrbitConstructorMenu        matlab.ui.container.Menu
        graphicalAnalysisMenu           matlab.ui.container.Menu
        celBodyCatalogMenu              matlab.ui.container.Menu
        LVDConsoleMenu                  matlab.ui.container.Menu
        uitoolbar1                      matlab.ui.container.Toolbar
        newMissionPlanToolbar           matlab.ui.container.toolbar.PushTool
        openMissionPlanToolbar          matlab.ui.container.toolbar.PushTool
        saveMissionPlanToolbar          matlab.ui.container.toolbar.PushTool
        panPushMenuToggle               matlab.ui.container.toolbar.ToggleTool
        rotatePushMenuToggle            matlab.ui.container.toolbar.ToggleTool
        zoomOutPushMenuToggle           matlab.ui.container.toolbar.ToggleTool
        zoomInPushMenuToggle            matlab.ui.container.toolbar.ToggleTool
        RestoreViewToolbarMenu          matlab.ui.container.toolbar.PushTool
        GridLayout16                    matlab.ui.container.GridLayout
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout23                    matlab.ui.container.GridLayout
        WarningsAlertsPanel             matlab.ui.container.Panel
        GridLayout24                    matlab.ui.container.GridLayout
        WarnAlertTable                  matlab.ui.control.Table
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        NonSequentialEventsPanel        matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        nonSeqEventsListbox             matlab.ui.control.ListBox
        deleteNonSeqEventButton         matlab.ui.control.Button
        insertNonSeqEventButton         matlab.ui.control.Button
        SequentialEventsPanel           matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        GridLayout10                    matlab.ui.container.GridLayout
        deleteEvent                     matlab.ui.control.Button
        moveEventUp                     matlab.ui.control.Button
        moveEventDown                   matlab.ui.control.Button
        scriptListbox                   matlab.ui.control.ListBox
        insertEventButton               matlab.ui.control.Button
        GridLayout4                     matlab.ui.container.GridLayout
        finalScStatePanel               matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        FinalSpacecraftStateTextArea    matlab.ui.control.TextArea
        initScStatePanel                matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        InitialSpacecraftStateTextArea  matlab.ui.control.TextArea
        uipanel1                        matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        outputText                      matlab.ui.control.TextArea
        DisplayPanel                    matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        Panel_3                         matlab.ui.container.Panel
        DisplayAxesGridLayout           matlab.ui.container.GridLayout
        dispAxes                        matlab.ui.control.UIAxes
        GridLayout12                    matlab.ui.container.GridLayout
        DispAxesTimeSlider              matlab.ui.control.Slider
        decrOrbitToPlotNum              matlab.ui.control.Button
        incrOrbitToPlotNum              matlab.ui.control.Button
        timeSliderValueLabel            matlab.ui.control.Label
        scriptResultsOutOfDateLbl       matlab.ui.control.Label
        dispAxisTitleLabel              matlab.ui.control.Label
        GridLayout19                    matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        titleText                       matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        BusyStatusLabel                 matlab.ui.control.Label
        BusyStatusIcon                  matlab.ui.control.Image
        scriptListboxContextMenu        matlab.ui.container.ContextMenu
        EditEventMenu                   matlab.ui.container.Menu
        toggleOptimForSelEventMenu      matlab.ui.container.Menu
        viewStateAfterSelectedEventMenu  matlab.ui.container.Menu
        createContConstraintsWithSelEvtMenu  matlab.ui.container.Menu
        copyUtAtStartOfSelEventMenu     matlab.ui.container.Menu
        copyUtAtEndOfSelEventMenu       matlab.ui.container.Menu
        copyDurationOfSelEventMenu      matlab.ui.container.Menu
        copyOrbitAfterSelectedEventMenu  matlab.ui.container.Menu
        advanceScriptToSelectedEventMenu  matlab.ui.container.Menu
        ConvertImpulsiveDVActionintoFiniteBurnMenu  matlab.ui.container.Menu
        uploadImpDvActionToKspMenu      matlab.ui.container.Menu
        outputContextMenu               matlab.ui.container.ContextMenu
        clearOutputMenu                 matlab.ui.container.Menu
        InitialOrbitDisplayTextContextMenu  matlab.ui.container.ContextMenu
        CopyInitialOrbitToClipboardMenu  matlab.ui.container.Menu
        ChangeInitialReferenceFrameElementSetMenu  matlab.ui.container.Menu
        FinalOrbitDisplayTextContextMenu  matlab.ui.container.ContextMenu
        CopyFinalOrbittoClipboardMenu   matlab.ui.container.Menu
        ChangeFinalReferenceFrameElementSetMenu  matlab.ui.container.Menu
    end

    properties (Access = private)
        warnErrTooltipLabel matlab.ui.control.Label
        warnErrTooltipPanel matlab.ui.container.Panel
        warnErrInvisibleAxes matlab.graphics.axis.Axes

        initialOrbitDisplayRefFrame AbstractReferenceFrame
        initialOrbitDisplayElementSet ElementSetEnum = ElementSetEnum.KeplerianElements;

        finalOrbitDisplayRefFrame AbstractReferenceFrame
        finalOrbitDisplayElementSet ElementSetEnum = ElementSetEnum.KeplerianElements;

        gridLayout23Resizer UIGridLayoutMouseDragResizer
        gridLayout3Resizer UIGridLayoutMouseDragResizer
        gridLayout7Resizer UIGridLayoutMouseDragResizer
        gridLayout4Resizer UIGridLayoutMouseDragResizer
    end
    
    events
        GenericStatusLabelUpdate
    end

    methods (Access = private)
        function addUndoState(~, handles, actionName)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            undoRedo = getappdata(handles.ma_LvdMainGUI,'undoRedo');

            undoRedo.addState(handles.ma_LvdMainGUI, lvdData, actionName);
        end

        function c = computeJacNonlconFunc(~, x, nonlcon)
            [cML, ceqML] = nonlcon(x);

            c = [cML(:)', ceqML(:)'];
        end

        function enableDisableArrowButtons(~, lvdData, handles)
            type = lvdData.viewSettings.selViewProfile.trajEvtsViewType;
            switch type
                case ViewEventsTypeEnum.All
                    handles.decrOrbitToPlotNum.Enable = 'off';
                    handles.incrOrbitToPlotNum.Enable = 'off';

                case ViewEventsTypeEnum.SoIChunk
                    handles.decrOrbitToPlotNum.Enable = 'on';
                    handles.incrOrbitToPlotNum.Enable = 'on';

                otherwise
                    error('Unknown event view type: %s', type.name);
            end
        end

        function gravSystemUpdateCallback(~, ~, ~, handles)
            if(isvalid(handles.ma_LvdMainGUI))
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                lvdData.celBodyData.resetAllParentNeedsUpdateFlags();
            end
        end

        function initializeOutputWindowText(~, ~, hOutputText)
            %                 write_to_output_func = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');

            statusBoxMsg = {['KSPTOT Launch Vehicle Designer v', getKSPTOTVersionNumStr(), sprintf(' (R%s)', version('-release'))], 'Written By Arrowstar (C) 2023', ...
                getLVD_HR(hOutputText)};
            hOutputText.Value = statusBoxMsg;
            %             	for(i=1:size(statusBoxMsg,2)) %#ok<ALIGN,*NO4LP>
            %                     if(i==1)
            %                         write_to_output_func(statusBoxMsg{i},'overwrite');
            %                     else
            %                         write_to_output_func(statusBoxMsg{i},'appendNoDate');
            %                     end
            %                 end
        end

        function integrationAbsTolMenu_Callback(app, ~, ~, handles)
            % hObject    handle to integrationAbsTolMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');

            input_str = sprintf(['Enter the desired integration absolute error tolerance:\n',...
                '(Minimum = 1E-14, Maximum = 1E-2)\n',...
                '(This will influence script execution speed.)']);
            str = inputdlg(input_str, 'Absolute Error Tolerance', [1 75], {num2str(lvdData.settings.intAbsTol,'%6.6E')});
            if(isempty(str))
                return;
            end

            str = str{1};

            if(checkStrIsNumeric(str) && str2double(str) >= 1E-14 && str2double(str) <= 1E-2)
                addUndoState(app, handles,'Edit Integrator Abs Tol');

                writeOutput(sprintf('Setting integration absolute error tolerance to %s.', str),'append');

                lvdData.settings.intAbsTol = str2double(str);

                runScript(app, handles, lvdData, 1);
                app.processData(handles)
            else
                writeOutput(sprintf('Could not set the desired integration absolute error tolerance.  "%s" is an invalid entry.', str),'append');
                beep;
            end
        end

        function integrationRelTolMenu_Callback(app, ~, ~, handles)
            % hObject    handle to integrationRelTolMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');

            input_str = sprintf(['Enter the desired integration relative error tolerance:\n',...
                '(Minimum = 1E-14, Maximum = 1E-2)\n',...
                '(This will influence script execution speed.)']);
            str = inputdlg(input_str, 'Relative Error Tolerance', [1 75], {num2str(lvdData.settings.intRelTol,'%6.6E')});
            if(isempty(str))
                return;
            end

            str = str{1};

            if(checkStrIsNumeric(str) && str2double(str) >= 1E-14 && str2double(str) <= 1E-2)
                addUndoState(app, handles,'Edit Integrator Rel Tol');

                writeOutput(sprintf('Setting integration relative error tolerance to %s.', str),'append');

                lvdData.settings.intRelTol = str2double(str);

                runScript(app, handles, lvdData, 1);
                app.processData(handles)
            else
                writeOutput(sprintf('Could not set the desired integration relative error tolerance.  "%s" is an invalid entry.', str),'append');
                beep;
            end
        end

        function saved = isMissionPlanSaved(~, handles)
            application_title = get(handles.ma_LvdMainGUI,'Name');
            if(application_title(end) == '*')
                saved = false;
            else
                saved = true;
            end
        end

        function openMissionPlanFile(app, filePath, write_to_output_func, application_title, celBodyData, FileName, handles)
            write_to_output_func(['Loading mission case from "', filePath,'"... '],'append');

            %             hMsg = helpdlg('Loading LVD Case File.  Please wait...','Launch Vehicle Designer');
            hMsg = uiprogressdlg(app.ma_LvdMainGUI, 'Message','Loading LVD Case File.  Please wait...', ...
                'Title', 'Launch Vehicle Designer', ...
                'Indeterminate','on');

            try
                load(filePath); %#ok<LOAD>
            catch ME %#ok<NASGU>
                write_to_output_func(['There was a problem loading the case file from disk: ',filePath,'.  Case not loaded.'],'append');

                if(isvalid(hMsg))
                    close(hMsg);
                end

                return;
            end

            if(isvalid(hMsg))
                close(hMsg);
            end

            if(exist('lvdData','var'))
                cla(app.dispAxes);

                setappdata(handles.ma_LvdMainGUI,'undoRedo',LVD_UndoRedoStateSet());

                app.addFilePathToRecentMissions(filePath, handles);

                topLevelBodyInfo = getTopLevelCentralBody(celBodyData);
                ldvDataTopLevelBodyInfo = getTopLevelCentralBody(lvdData.celBodyData); %#ok<NODEF>
                if(isprop(lvdData,'celBodyData') && ...
                        length(fields(topLevelBodyInfo)) == length(fields(ldvDataTopLevelBodyInfo)))
                    celBodyData = lvdData.celBodyData;
                    setappdata(handles.ma_LvdMainGUI,'celBodyData',celBodyData);
                else
                    lvdData.celBodyData = celBodyData;
                end

                if(isprop(lvdData,'celBodyData'))
                    if(not(isa(lvdData.celBodyData,'CelestialBodyData')))
                        lvdData.celBodyData = CelestialBodyData(lvdData.celBodyData);
                    end

                    names = fieldnames(lvdData.celBodyData);
                    for(i=1:length(names)) %#ok<*NO4LP>
                        name = names{i};
                        lvdData.celBodyData.(name).celBodyData = celBodyData;
                        lvdData.celBodyData.(name).getParBodyInfo(lvdData.celBodyData);
                    end
                end

                set(handles.ma_LvdMainGUI,'Name',[application_title, ' - ', FileName]);
                write_to_output_func(['Done.'],'appendSameLine');

                setappdata(handles.ma_LvdMainGUI,'lvdData',lvdData);
                setappdata(handles.ma_LvdMainGUI,'current_save_location',filePath);

                app.initialOrbitDisplayRefFrame = AbstractReferenceFrame.empty(1,0);
                app.initialOrbitDisplayElementSet = ElementSetEnum.KeplerianElements;
                app.finalOrbitDisplayRefFrame = AbstractReferenceFrame.empty(1,0);
                app.finalOrbitDisplayElementSet = ElementSetEnum.KeplerianElements;

                setDeleteButtonEnable(app, lvdData, handles);
                setNonSeqDeleteButtonEnable(app, lvdData, handles)

                setappdata(app.DispAxesTimeSlider,'lastTime',NaN);
                timeSliderCb = @(src,evt) timeSliderStateChanged(src,evt, lvdData, handles, app);
                app.DispAxesTimeSlider.ValueChangingFcn = timeSliderCb;

                enableDisableArrowButtons(app, lvdData, handles);

                addlistener(lvdData.script, 'EventPropagationStarted', @(src,evt) app.handleEvtPropagationStarted(src, evt));
                addlistener(lvdData.script, 'ScriptPropagationStarted', @(src,evt) app.handleScriptPropagationStarted(src, evt));
                addlistener(lvdData.script, 'ScriptPropagationFinished', @(src,evt) app.handleScriptPropagationEnded(src, evt));

                if(lvdData.optimizer.usesParallel())
                    numWorkers = lvdData.optimizer.getSelectedOptimizer().getNumParaWorkers();
                    startParallelPool(app.ma_LvdMainGUI, write_to_output_func, numWorkers);
                end

                propagateScript(app, handles, lvdData, 1);
                app.processData(handles)

            else
                write_to_output_func(['There was a problem loading the case file from disk: ',filePath,'.  Case not loaded.'],'append');
            end
        end

        function popOutOrbitDispDelFcn(app, ~, ~, inlineDispAxes, handles)
            handles.dispAxes = inlineDispAxes;

            app.processData(handles)
        end

        function propagateScript(app, handles, lvdData, evtStartNum)
            writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');

            if(handles.scriptResultsOutOfDateLbl.Visible == true)
                handles.scriptResultsOutOfDateLbl.Visible = 'off';
                drawnow nocallbacks;
            end

            lvdData.validation.clearOutputs();

            isSparseOutput = lvdData.settings.isSparseOutput;
            dispEvtPropTimes = lvdData.settings.disEvtPropTimes;

            evt = lvdData.script.getEventForInd(evtStartNum);

            t = tic;
            lvdData.script.executeScript(isSparseOutput, evt, true, false, dispEvtPropTimes, true);
            execTime = toc(t);

            handles.warnAlertsSlider.Value = 0;

            lvdData.validation.validate();
            updateWarnErrorLabels(app, handles, true);

            setappdata(app.DispAxesTimeSlider,'lastTime',NaN);

            writeOutput(sprintf('Executed mission script in %.3f seconds.',execTime),'append');
            drawnow limitrate nocallbacks;
        end

        function recentMissionCallback(app, ~, ~, missionFileName, handles)
            if(isMissionPlanSaved(app, handles))
                askToClear = false;
            else
                askToClear = true;
            end

            if(askToClear)
                response = uiconfirm(app.ma_LvdMainGUI,'All unsaved work will be lost.  Continue?', ...
                    'Launch Vehicle Designer', ...
                    'Options',{'Yes','No'}, ...
                    'DefaultOption','Yes');
            else
                response = 'Yes';
            end

            if(~strcmpi(response,'Yes'))
                return;
            end

            celBodyData = getappdata(handles.ma_LvdMainGUI,'celBodyData');
            write_to_output_func = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
            application_title = getappdata(handles.ma_LvdMainGUI,'application_title');

            [~,name,ext] = fileparts(missionFileName);
            FileName = [name,ext];

            openMissionPlanFile(app, missionFileName, write_to_output_func, application_title, celBodyData, FileName, handles);
        end

        function recordFinalAxesPanZoomAfterRotation(app, ~, ~, handles)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            if(not(lvdData.viewSettings.selViewProfile.updateViewAxesLimits))
                hDispAxes = app.dispAxes;
                temp = [xlim(hDispAxes);
                        ylim(hDispAxes);
                        zlim(hDispAxes)];
                lvdData.viewSettings.selViewProfile.viewZoomAxLims = temp;
  
                [az,el] = view(handles.dispAxes);
                lvdData.viewSettings.selViewProfile.viewAzEl = [az,el];
    
                lvdData.viewSettings.selViewProfile.viewCameraPosition = handles.dispAxes.CameraPosition;
                lvdData.viewSettings.selViewProfile.viewCameraTarget = handles.dispAxes.CameraTarget;
                lvdData.viewSettings.selViewProfile.viewCameraUpVector = handles.dispAxes.CameraUpVector;
                lvdData.viewSettings.selViewProfile.viewCameraViewAngle = handles.dispAxes.CameraViewAngle;
            end
        end

        function recordFinalAxesViewAfterRotation(app, ~, ~, handles)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            hDispAxes = app.dispAxes;
            temp = [xlim(hDispAxes);
                    ylim(hDispAxes);
                    zlim(hDispAxes)];
            lvdData.viewSettings.selViewProfile.viewZoomAxLims = temp;

            [az,el] = view(handles.dispAxes);
            lvdData.viewSettings.selViewProfile.viewAzEl = [az,el];
        end

        function runScript(app, handles, lvdData, evtStartNum)
            if(lvdData.settings.autoPropScript)
                propagateScript(app, handles, lvdData, evtStartNum);
            else
                handles.scriptResultsOutOfDateLbl.Visible = 'on';
            end
            setappdata(app.DispAxesTimeSlider,'lastTime',NaN);
        end

        function saveMission(~, handles, varargin)
            if(length(varargin) == 1)
                filePath = varargin{1};
                [~,name,ext] = fileparts(filePath);
                FileName = [name,ext];
            else
                current_save_location = getappdata(handles.ma_LvdMainGUI,'current_save_location');

                if(isempty(current_save_location))
                    fileNameToDefaultTo = 'mission.mat';
                else
                    [current_save_location_path,~,~] = fileparts(current_save_location);
                    fileNameToDefaultTo = [current_save_location_path,filesep,'mission.mat'];
                end

                [FileName,PathName] = uiputfile({'*.mat','KSPTOT Launch Vehicle Designer Case File (*.mat)'},...
                    'Save Launch Vehicle Designer Case',...
                    fileNameToDefaultTo);
                if(ischar(FileName) && ischar(PathName))
                    filePath = [PathName,FileName];
                else
                    return;
                end
            end

            write_to_output_func = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
            write_to_output_func(['Saving mission case as "', filePath,'"... '],'append'); 
            drawnow nocallbacks;

            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            lvdData.ksptotVer = getKSPTOTVersionNumStr();

            save(filePath,'lvdData');
            application_title = getappdata(handles.ma_LvdMainGUI,'application_title');

            set(handles.ma_LvdMainGUI,'Name',[application_title, ' - ', FileName]);
            setappdata(handles.ma_LvdMainGUI,'current_save_location',filePath);

            write_to_output_func(['Done.'],'appendSameLine');
            drawnow nocallbacks;
        end

        function saveMissionAs(app, handles)
            current_save_location = getappdata(handles.ma_LvdMainGUI,'current_save_location');

            if(isempty(current_save_location))
                fileNameToDefaultTo = 'mission.mat'; %#ok<NASGU>
            else
                [current_save_location_path,~,~] = fileparts(current_save_location);
                fileNameToDefaultTo = [current_save_location_path,filesep,'mission.mat']; %#ok<NASGU>
            end

            [FileName,PathName] = uiputfile({'*.mat','KSPTOT Launch Vehicle Designer Case File (*.mat)'},...
                'Save Launch Vehicle Designer Case',...
                current_save_location);
            if(ischar(FileName) && ischar(PathName))
                filePath = [PathName,FileName];
                saveMission(app, handles, filePath);

                app.addFilePathToRecentMissions(filePath, handles);
            end
        end

        function setDeleteButtonEnable(~, lvdData, handles)
            numEvents = lvdData.script.getTotalNumOfEvents();
            if(numEvents <= 1)
                handles.deleteEvent.Enable = 'off';
            else
                handles.deleteEvent.Enable = 'on';
            end
        end

        function setNonSeqDeleteButtonEnable(~, lvdData, handles)
            numEvents = lvdData.script.nonSeqEvts.getTotalNumOfEvents();
            if(numEvents <= 0)
                handles.deleteNonSeqEventButton.Enable = 'off';
            else
                handles.deleteNonSeqEventButton.Enable = 'on';
            end
        end

        function setResizeData(~, hObject, handles)
            pos = hObject.Position;
            origWidth = pos(3);
            origHgt = pos(4);
            setappdata(hObject,'OriginalWidth',origWidth);
            setappdata(hObject,'OriginalHeight',origHgt);

            %title label
            titlePos = handles.titleText.Position;
            titleOffsetFromTop = origHgt - (titlePos(2) + titlePos(4));
            setappdata(hObject,'titleOffsetFromTop',titleOffsetFromTop);

            %initial state panel
            initScStatePanelPos = handles.initScStatePanel.Position;
            initScStatePanelOffsetFromTop = origHgt - (initScStatePanelPos(2) + initScStatePanelPos(4));
            setappdata(hObject,'initScStatePanelOffsetFromTop',initScStatePanelOffsetFromTop);

            %final state panel
            finalScStatePanelPos = handles.finalScStatePanel.Position;
            finalScStatePanelOffsetFromTop = origHgt - (finalScStatePanelPos(2) + finalScStatePanelPos(4));
            setappdata(hObject,'finalScStatePanelOffsetFromTop',finalScStatePanelOffsetFromTop);

            %display axes label
            dispAxisTitleLabelPos = handles.dispAxisTitleLabel.Position;
            dispAxisTitleLabelOffsetFromTop = origHgt - (dispAxisTitleLabelPos(2) + dispAxisTitleLabelPos(4));
            setappdata(hObject,'dispAxisTitleLabelOffsetFromTop',dispAxisTitleLabelOffsetFromTop);

            %display axes
            %     dispAxesPos = handles.dispAxes.Position;
            %     dispAxesOffsetFromTop = origHgt - (dispAxesPos(2) + dispAxesPos(4));
            %     setappdata(hObject,'dispAxesOffsetFromTop',dispAxesOffsetFromTop);

            %display axes panel
            dispAxesPanelPos = handles.dispAxesPanel.Position;
            dispAxesPanelOffsetFromTop = origHgt - (dispAxesPanelPos(2) + dispAxesPanelPos(4));
            setappdata(hObject,'dispAxesPanelOffsetFromTop',dispAxesPanelOffsetFromTop);

            %insert seq event button
            insertEventButtonPos = handles.insertEventButton.Position;
            insertEventButtonOffsetFromTop = origHgt - (insertEventButtonPos(2) + insertEventButtonPos(4));
            setappdata(hObject,'insertEventButtonOffsetFromTop',insertEventButtonOffsetFromTop);

            %script listbox
            scriptListboxPos = handles.scriptListbox.Position;
            scriptListboxOffsetFromTop = origHgt - (scriptListboxPos(2) + scriptListboxPos(4));
            setappdata(hObject,'scriptListboxOffsetFromTop',scriptListboxOffsetFromTop);

            %incrOrbitToPlotNum Button
            incrOrbitToPlotNumPos = handles.incrOrbitToPlotNum.Position;
            incrOrbitToPlotNumOffsetFromRight = origWidth - (incrOrbitToPlotNumPos(1) + incrOrbitToPlotNumPos(3));
            setappdata(hObject,'incrOrbitToPlotNumOffsetFromRight',incrOrbitToPlotNumOffsetFromRight);

            %timeSliderValueLabel
            timeSliderValueLabelPos = handles.timeSliderValueLabel.Position;
            timeSliderValueLabelOffsetFromRight = origWidth - (timeSliderValueLabelPos(1) + timeSliderValueLabelPos(3));
            setappdata(hObject,'timeSliderValueLabelOffsetFromRight',timeSliderValueLabelOffsetFromRight);

            %timeSliderPanel
            timeSliderPanelPos = handles.timeSliderPanel.Position;
            timeSliderPanelOffsetFromRight = origWidth - (timeSliderPanelPos(1) + timeSliderPanelPos(3));
            setappdata(hObject,'timeSliderPanelOffsetFromRight',timeSliderPanelOffsetFromRight);

            %warningAlertPanel
            warningAlertPanelPos = handles.warningAlertPanel.Position;
            warningAlertPanelOffsetFromRight = origWidth - (warningAlertPanelPos(1) + warningAlertPanelPos(3));
            setappdata(hObject,'warningAlertPanelOffsetFromRight',warningAlertPanelOffsetFromRight);

            %warnAlertsSlider
            warnAlertsSliderPos = handles.warnAlertsSlider.Position;
            warnAlertsSliderOffsetFromRight = warningAlertPanelPos(3) - (warnAlertsSliderPos(1) + warnAlertsSliderPos(3));
            setappdata(handles.warningAlertPanel,'warnAlertsSliderOffsetFromRight',warnAlertsSliderOffsetFromRight);

            %warning1Lbl (and others
            warningLblPos = handles.warning1Lbl.Position;
            warningLblOffsetFromRight = warningAlertPanelPos(3) - (warningLblPos(1) + warningLblPos(3));
            setappdata(handles.warningAlertPanel,'warningLblOffsetFromRight',warningLblOffsetFromRight);
        end

        function showChildBodyMarker_Callback(~, ~, ~, handles)
            % hObject    handle to showChildBodyMarker (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of showChildBodyMarker
            app.processData(handles);
        end

        function showChildrenCheckBox_Callback(~, ~, ~, handles)
            % hObject    handle to showChildrenCheckBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of showChildrenCheckBox
            app.processData(handles);
        end

        function showSoICheckBox_Callback(~, ~, ~, handles)
            % hObject    handle to showSoICheckBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of showSoICheckBox
            app.processData(handles);
        end

        function timeSliderKeyPressCallback(~, src, evt, ~)
            if(javaMethodEDT('getKeyCode',evt) == java.awt.event.KeyEvent.VK_RIGHT || ...
                    javaMethodEDT('getKeyCode',evt) == java.awt.event.KeyEvent.VK_LEFT || ...
                    javaMethodEDT('getKeyCode',evt) == java.awt.event.KeyEvent.VK_KP_RIGHT || ...
                    javaMethodEDT('getKeyCode',evt) == java.awt.event.KeyEvent.VK_KP_LEFT)

                src.ValueChangingFcn(src,[]);
            end
        end

        function updateWarnErrorLabels(app, handles, updateSlider)
            lvdData = getappdata(app.ma_LvdMainGUI,'lvdData');

            % hLabels = [handles.warning1Lbl, handles.warning2Lbl, handles.warning3Lbl, ...
            %     handles.warning4Lbl, handles.warning5Lbl, handles.warning6Lbl];

            % lvdData.validation.writeOutputsToUI(handles.warnAlertsSlider, hLabels, updateSlider);
            lvdData.validation.writeOutputsToUITable(app.WarnAlertTable);
        end

        function viewProfileMenuSelectedCallback(app, src, ~, profile, viewSettings, handles)
            viewSettings.setProfileAsActive(profile);

            menus = src.Parent.Children;
            for(i=1:length(menus))
                delete(menus(i));
            end

            setappdata(app.DispAxesTimeSlider,'lastTime',NaN);

            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            lvdData.validation.clearOutputs();
            lvdData.validation.validate();
            updateWarnErrorLabels(app, handles, true);

            app.processData(handles);
        end

        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.

            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.panPushMenuToggle, app.rotatePushMenuToggle, app.zoomOutPushMenuToggle, app.zoomInPushMenuToggle];
            interactiveTools(event.Source == interactiveTools) = [];

            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');

            % Set figure interactions to 'off'.
            datacursormode(app.ma_LvdMainGUI, 'off')
            rotate3d(app.ma_LvdMainGUI, 'off');
            pan(app.ma_LvdMainGUI, 'off');
            zoom(app.ma_LvdMainGUI,'off');

            app.InitialSpacecraftStateTextArea.ContextMenu = app.InitialOrbitDisplayTextContextMenu;
            app.FinalSpacecraftStateTextArea.ContextMenu = app.FinalOrbitDisplayTextContextMenu;
        end

        function handleScriptPropagationStarted(app, src, evt)
            arguments
                app(1,1) ma_LvdMainGUI_App
                src(1,1) LaunchVehicleScript
                evt
            end

            app.BusyStatusIcon.Visible = 'on';
            app.BusyStatusLabel.Visible = 'on';

            drawnow nocallbacks;
        end

        function handleEvtPropagationStarted(app, src, evt)
            arguments
                app(1,1) ma_LvdMainGUI_App
                src(1,1) LaunchVehicleScript
                evt(1,1) ScriptEventPropagationData
            end

            app.BusyStatusLabel.Text = sprintf('Propagating Event %u "%s"', evt.event.getEventNum(), evt.event.name);
            drawnow limitrate nocallbacks;
        end

        function handleScriptPropagationEnded(app, src, evt)
            arguments
                app(1,1) ma_LvdMainGUI_App
                src(1,1) LaunchVehicleScript
                evt
            end

            app.BusyStatusIcon.Visible = 'off';
            app.BusyStatusLabel.Visible = 'off';
            drawnow nocallbacks;
        end

        function processData(app, handles)
            global GLOBAL_AppThemer %#ok<GVMIS>

            %lvd_processData Summary of this function goes here
            %   Detailed explanation goes here
            app.BusyStatusIcon.Visible = 'on';
            app.BusyStatusLabel.Visible = 'on';
            app.BusyStatusLabel.Text = 'Generating display data, please wait...';
            drawnow nocallbacks;

            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            % celBodyData = lvdData.celBodyData;

            % maStateLog = lvdData.stateLog.getMAFormattedStateLogMatrix(true);

            %%%%%%%%%%
            % Update script listbox
            %%%%%%%%%%
            [evtListboxStr, events] = lvdData.script.getListboxStr();
            app.scriptListbox.Items = evtListboxStr;
            app.scriptListbox.ItemsData = events;
            
            removeStyle(app.scriptListbox);
            if(lvdData.viewSettings.selViewProfile.scriptBoxUseEventColors)
                colorLineSpecs = [events.colorLineSpec];
                colorSpecs = [colorLineSpecs.color];
                bgColorRgb = ColorSpecEnum.getBestGreyBackgroundColor(colorSpecs);
                for(i=1:length(events))
                    % iconRGB = events(i).colorLineSpec.color.iconImageRBG;
                    % s = uistyle('Icon',iconRGB, 'IconAlignment','rightmargin');
                    s = uistyle('FontColor',events(i).colorLineSpec.color.color);
                    addStyle(app.scriptListbox, s, "item", i);
                end
                addStyle(app.scriptListbox,uistyle('BackgroundColor',bgColorRgb));
                app.scriptListbox.BackgroundColor = bgColorRgb;
            else
                app.scriptListbox.BackgroundColor = GLOBAL_AppThemer.selTheme.listboxBgColor;
                app.scriptListbox.FontColor = GLOBAL_AppThemer.selTheme.listboxFontColor;
            end

            scriptListVal = get(handles.scriptListbox,'Value');
            if(isempty(scriptListVal) || scriptListVal <= 0)
                set(handles.scriptListbox,'Value',1);
            elseif(scriptListVal > length(get(handles.scriptListbox,'String')))
                set(handles.scriptListbox,'Value',length(get(handles.scriptListbox,'String')));
            end
%             drawnow nocallbacks;

            %%%%%%%%%%
            % Update Non-Sequential Events listbox
            %%%%%%%%%%
            [nonSeqEvtsListboxStr, nonSeqEvents] = lvdData.script.nonSeqEvts.getListboxStr();

            if(isempty(nonSeqEvtsListboxStr))
                set(handles.nonSeqEventsListbox,'String','');
            else
                app.nonSeqEventsListbox.Items = nonSeqEvtsListboxStr;
                app.nonSeqEventsListbox.ItemsData = nonSeqEvents;
            end

            scriptListVal = get(handles.nonSeqEventsListbox,'Value');
            if(isempty(scriptListVal) || scriptListVal <= 0)
                set(handles.nonSeqEventsListbox,'Value',1);
            elseif(scriptListVal > length(get(handles.nonSeqEventsListbox,'String')))
                set(handles.nonSeqEventsListbox,'Value',length(get(handles.nonSeqEventsListbox,'String')));
            end
            drawnow nocallbacks;

            %%%%%%%%%%
            % Redraw plots
            %%%%%%%%%%
            lvdData.viewSettings.plotTrajectoryWithActiveViewProfile(handles, app);
%             drawnow nocallbacks;

            %%%%%%%%%%
            % Update State Readouts
            %%%%%%%%%%
            [stateStr, stateTooltipStr, clipboardData] = lvd_UpdateStateReadout(app.initialOrbitDisplayRefFrame, app.initialOrbitDisplayElementSet, 'initial', lvdData.stateLog, true);
            app.InitialSpacecraftStateTextArea.Value = stateStr;
            app.InitialSpacecraftStateTextArea.Tooltip = stateTooltipStr;
            app.InitialSpacecraftStateTextArea.UserData = clipboardData;

            [stateStr, stateTooltipStr, clipboardData] = lvd_UpdateStateReadout(app.finalOrbitDisplayRefFrame, app.finalOrbitDisplayElementSet, 'final', lvdData.stateLog, true);
            app.FinalSpacecraftStateTextArea.Value = stateStr;
            app.FinalSpacecraftStateTextArea.Tooltip = stateTooltipStr;
            app.FinalSpacecraftStateTextArea.UserData = clipboardData;

            app.BusyStatusIcon.Visible = 'off';
            app.BusyStatusLabel.Visible = 'off';
            app.BusyStatusLabel.Text = '';

            drawnow nocallbacks;
        end

        function handleGenericStatusLabelUpdateEvent(app, src, evt)
            arguments
                app(1,1) ma_LvdMainGUI_App
                src(1,1) ma_LvdMainGUI_App
                evt(1,1) GenericStatusLabelUpdate
            end

            app.BusyStatusLabel.Text = evt.labelText;
            drawnow nocallbacks;
        end

        function convertDeltaVAction(app, action)
            arguments
                app(1,1) ma_LvdMainGUI_App
                action(1,1) AddDeltaVAction
            end

            event = action.event;
            script = event.script;
            lvdData = script.lvdData;
            actionInd = find(event.actions == action,1,'first');

            if(actionInd > 1)
                beforeActions = event.actions(1:actionInd-1);
            else
                beforeActions = AbstractEventAction.empty(1,0);
            end

            if(actionInd < length(event.actions))
                afterActions = event.actions(actionInd+1:end);
            else
                afterActions = AbstractEventAction.empty(1,0);
            end

            if(event.execActionsNode == ActionExecNodeEnum.BeforeProp)
                %                 stateLogEntry = lvdData.stateLog.getFirstStateLogForEvent(event);
                subStateLog = lvdData.stateLog.getAllStateLogEntriesForEvent(event);
                if(length(subStateLog) >= 2)
                    stateLogEntryKinematics = subStateLog(2);
                    stateLogEntryMass = subStateLog(1);
                else
                    %                     stateLogEntry = subStateLog(1);
                    stateLogEntryKinematics = subStateLog(1);
                    stateLogEntryMass = subStateLog(1);
                end
            else
                %TODO Check this
                stateLogEntryKinematics = lvdData.stateLog.getLastStateLogForEvent(event);
                stateLogEntryMass = stateLogEntryKinematics;
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Create slew to burn attitude event
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            attEvent = LaunchVehicleEvent(script);
            attEvent.name = 'Slew to Burn Attitude';
            attEvent.execActionsNode = ActionExecNodeEnum.BeforeProp;

            %%% Create termination condition
            termCond = EventDurationTermCondition(300);
            attEvent.termCond = termCond;

            %%% Create Attitude Interpolation Steering Model
            steeringModel = GenericQuatInterpSteeringModel.getDefaultSteeringModel();
            steeringModel.refFrame = stateLogEntryKinematics.centralBody.getBodyCenteredInertialFrame();

            ut = stateLogEntryKinematics.time;
            rVect = stateLogEntryKinematics.position;
            vVect = stateLogEntryKinematics.velocity;
            bodyInfo = stateLogEntryKinematics.centralBody;
            dcm = stateLogEntryKinematics.attitude.dcm;

            switch action.frame
                case DeltaVFrameEnum.Inertial
                    steeringModel.controlFrame = InertialControlFrame();
                    dvVectInertial = action.deltaVVect;

                    r = vrrotvec([1;0;0], dvVectInertial);
                    M = axang2rotmARH(r);

                    [gammaAngle, betaAngle, alphaAngle] = getAnglesFromInertialBodyAxes_Func(M, ut, rVect, vVect, bodyInfo, bodyInfo.getBodyCenteredInertialFrame());

                case DeltaVFrameEnum.OrbitNtw
                    steeringModel.controlFrame = WindControlFrame();
                    dvVectInertial = getNTW2ECIdvVect(action.deltaVVect, stateLogEntryKinematics.position, stateLogEntryKinematics.velocity);

                    r = vrrotvec([1;0;0], dvVectInertial);
                    M = axang2rotmARH(r);

                    [gammaAngle,betaAngle,alphaAngle] = computeInertialAeroAnglesFromBodyAxes(ut, rVect, vVect, bodyInfo, M(:,1), M(:,2), M(:,3));

                otherwise
                    error('%Unknown DV frame: %s', action.frame.name);
            end

            steeringModel.setContinuityTerms(true);
            steeringModel.tDur = 180;

            steeringModel.gamma1 = gammaAngle;
            steeringModel.beta1 = betaAngle;
            steeringModel.alpha1 = alphaAngle;

            steeringModel.setConstsFromDcmAndContinuitySettings(dcm, ut, rVect, vVect, bodyInfo)

            %%% Create Steering Model Optimization
            var = steeringModel.getNewOptVar();
            lvdData.optimizer.vars.addVariable(var);

            var.varTDur = false;
            var.varGamma0 = false;
            var.varBeta0 = false;
            var.varAlpha0 = false;
            var.varGamma1 = false;
            var.varBeta1 = true;
            var.varAlpha1 = true;

            var.lb([5,7]) = [-pi/2, -2*pi];
            var.ub([5,7]) = [ pi/2,  2*pi];

            %%% Create Attitude Interpolation Action
            steerAction = SetSteeringModelAction(steeringModel);
            steerAction.event = attEvent;
            attEvent.addAction(steerAction);

            %%% Create Zero Throttle Action
            throttleModel = ThrottlePolyModel.getDefaultThrottleModel();
            throttleModel.throttleModel.constTerm = 0;
            throttleModel.throttleModel.linearTerm = 0;
            throttleModel.throttleModel.accelTerm = 0;

            throttleAction = SetThrottleModelAction(throttleModel);
            throttleAction.event = attEvent;
            attEvent.addAction(throttleAction);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Create execute burn event
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            burnEvent = LaunchVehicleEvent(script);
            burnEvent.name = 'Execute Burn';
            burnEvent.execActionsNode = ActionExecNodeEnum.BeforeProp;

            %%% Create Burn Duration Termination Condition
            [tankMDots, totalThrust, ~] = AddDeltaVAction.getTankMDotsAndTotalThrustForStateLogEntry(stateLogEntryMass);

            if(abs(sum(tankMDots)) > 0)
                m0Kg = stateLogEntryMass.getTotalVehicleMass() * 1000;
                tankMDotsKgS = tankMDots * 1000;
                totalMDotKgS = sum(tankMDotsKgS);
                totalThrustN = totalThrust * 1000;
                effIsp = totalThrustN / (getG0() * abs(totalMDotKgS)); %sec

                dvMag = norm(action.deltaVVect);

                burnDur = getBurnDuration(m0Kg, abs(totalMDotKgS), effIsp, dvMag);
            else
                burnDur = 1;
                %warning here
            end

            termCond = EventDurationTermCondition(burnDur);
            burnEvent.termCond = termCond;

            %%% Create term cond variable
            var = termCond.getNewOptVar();
            lvdData.optimizer.vars.addVariable(var);

            var.lb = 0;
            var.ub = 2*burnDur;
            var.useTf = true;

            %%% Create 100% Throttle Action
            throttleModel = ThrottlePolyModel.getDefaultThrottleModel();
            throttleModel.throttleModel.constTerm = 1;
            throttleModel.throttleModel.linearTerm = 0;
            throttleModel.throttleModel.accelTerm = 0;

            throttleAction = SetThrottleModelAction(throttleModel);
            throttleAction.event = burnEvent;
            burnEvent.addAction(throttleAction);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Create clean up burn event
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cleanupEvent = LaunchVehicleEvent(script);
            cleanupEvent.name = 'Cleanup Burn';
            cleanupEvent.execActionsNode = ActionExecNodeEnum.BeforeProp;

            %%% Create termination condition
            termCond = EventDurationTermCondition(0);
            cleanupEvent.termCond = termCond;

            %%% Create Zero Throttle Action
            throttleModel = ThrottlePolyModel.getDefaultThrottleModel();
            throttleModel.throttleModel.constTerm = 0;
            throttleModel.throttleModel.linearTerm = 0;
            throttleModel.throttleModel.accelTerm = 0;

            throttleAction = SetThrottleModelAction(throttleModel);
            throttleAction.event = cleanupEvent;
            cleanupEvent.addAction(throttleAction);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Adjust other events to accomodate
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            event.removeAction(action);
            eventNum = event.getEventNum();

            if(event.execActionsNode == ActionExecNodeEnum.BeforeProp)
                if(isempty(beforeActions))
                    script.addEventAtInd(attEvent, eventNum-1);
                    script.addEventAtInd(burnEvent, eventNum);
                    script.addEventAtInd(cleanupEvent, eventNum+1);

                else
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Create Placeholder Initial Event and Move Before Actions
                    % Over to It
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    placeholderEvent = LaunchVehicleEvent(script);
                    placeholderEvent.name = sprintf('%s (1 of 2)', event.name);
                    event.name = sprintf('%s (2 of 2)', event.name);

                    %%% Create termination condition for placeholder
                    termCond = EventDurationTermCondition(0);
                    placeholderEvent.termCond = termCond;

                    %%% Add before actions to placeholder and remove from
                    %%% selected event
                    for(i=1:length(beforeActions))
                        placeholderEvent.addAction(beforeActions(i));
                        beforeActions(i).event = placeholderEvent;
                        event.removeAction(beforeActions(i))
                    end

                    script.addEventAtInd(placeholderEvent, eventNum-1);
                    script.addEventAtInd(attEvent, eventNum);
                    script.addEventAtInd(burnEvent, eventNum+1);
                    script.addEventAtInd(cleanupEvent, eventNum+2);
                end

            elseif(event.execActionsNode == ActionExecNodeEnum.AfterProp)
                if(isempty(afterActions))
                    script.addEventAtInd(attEvent, eventNum);
                    script.addEventAtInd(burnEvent, eventNum+1);
                    script.addEventAtInd(cleanupEvent, eventNum+2);

                else
                    placeholderEvent = LaunchVehicleEvent(script);
                    placeholderEvent.name = sprintf('%s (2 of 2)', event.name);
                    event.name = sprintf('%s (1 of 2)', event.name);

                    %%% Create termination condition for placeholder
                    termCond = EventDurationTermCondition(0);
                    placeholderEvent.termCond = termCond;

                    %%% Add before actions to placeholder and remove from
                    %%% selected event
                    for(i=1:length(afterActions))
                        placeholderEvent.addAction(afterActions(i));
                        afterActions(i).event = placeholderEvent;
                        event.removeAction(afterActions(i))
                    end

                    script.addEventAtInd(attEvent, eventNum);
                    script.addEventAtInd(burnEvent, eventNum+1);
                    script.addEventAtInd(cleanupEvent, eventNum+2);
                    script.addEventAtInd(placeholderEvent, eventNum+3);

                end
            end

            msg = "The impulsive delta-v action has been converted into a finite burn made up of three events: a rotation to burn attitude, the burn itself, and cleanup from the burn." + newline + ...
                newline + ...
                "To proceed, consider doing the following:" + newline + ...
                newline + ...
                "1) Rename the newly created events as desired." + newline + ...
                "2) Update the steering model in the cleanup event to move the spacecraft to a more appropriate attitude." + newline + ...
                "3) In the Slew to Burn Attitude event, verify that the reference frame of the steering model is correct." + newline + ...
                "4) Verify that the correct engines and stages are active prior to and after the inserted burn." + newline + ...
                "5) Review the actions and termination conditions of the newly created events.  Verify optimization variables and their bounds." + newline + ...
                "6) If the selected event was split into two events, verify that actions were split correctly and update plotting, force model, and integrator options on both events as needed." + newline + ...
                "7) Run the optimizer to retarget your trajectory with the finite burn.";
            uialert(app.ma_LvdMainGUI, msg, 'Impulsive Delta-V Converted', "Icon","success");
        end

        function addFilePathToRecentMissions(app, filePath, handles)
            appOptions = getAppOptionsFromFile();
            recentMsnCaseFilesStr = appOptions.ksptot.lvdrecentmsncasefiles;
            recentMsnCaseFilesStr = [filePath,';',recentMsnCaseFilesStr];

            cases = strsplit(recentMsnCaseFilesStr,';');
            cases = cases(~cellfun('isempty',cases));

            indToRemove = [];
            for(i=1:length(cases))
                if(exist(cases{i},'file') == 0)
                    indToRemove(end+1) = i; %#ok<AGROW>
                end
            end
            cases(indToRemove) = [];

            if(numel(cases) > 10)
                cases = cases(1:10);
            end
            cases = unique(cases,'stable');
            recentMsnCaseFilesStr = strjoin(cases,';');

            % hKsptotMainGUI = getappdata(handles.ma_LvdMainGUI,'ksptotMainGUI');
            updateAppOptions('ksptot', 'lvdrecentmsncasefiles', recentMsnCaseFilesStr);
        end

        function flag = rotatePanZoomButtonDownFilter(app, src, evt)
            obj = src;
            flag = true;
            while(not(isempty(obj.Parent)))
                if(obj == app.dispAxes)
                    flag = false;
                    break;
                end

                obj = obj.Parent;
            end

            %             if(isprop(src,'ContextMenu') && ~isempty(src.ContextMenu))
            %                 flag = true;
            %             else
            %                 flag = false;
            %             end
        end
        
        function [tf, mousePt] = isMouseOverWarnErrLbl(app, warnLblObj)
%             obj = warnLblObj;
%             pos = obj.Position;
            
            pos = getpixelposition(warnLblObj,true) - [10, 35, 0, 0]; %we subtract this constant offset because it makes the math work.
%             while(not(isempty(obj.Parent)))
%                 try
%                     parentObj = obj.Parent;
%                     
%                     if(not(isa(parentObj, 'matlab.ui.Figure')))
%                         parentPos = parentObj.Position;
%                         pos(1:2) = pos(1:2) + parentPos(1:2);
%                     
%                         obj = obj.Parent;
%                     else
%                         break;
%                     end
%                 catch ME
%                     obj = obj.Parent;
%                 end
%             end
            
            mousePt = app.ma_LvdMainGUI.CurrentPoint;
            
            if(mousePt(1) >= pos(1) && ...
               mousePt(1) <= pos(1) + pos(3) && ...
               mousePt(2) >= pos(2) + 34 && ...
               mousePt(2) <= pos(2) + pos(4) + 34)
                tf = true;
            else
                tf = false;
            end
        end
        
        function resetCameraToolbarManagerCurrentObj(app)
            if(isprop(app.ma_LvdMainGUI,'CameraToolbarManager'))
                manager = app.ma_LvdMainGUI.CameraToolbarManager;
                manager.CurrentObj = [];
            end

            app.scriptListbox.ContextMenu = app.scriptListboxContextMenu;
            app.outputText.ContextMenu = app.outputContextMenu;
        end
        
        function handleThemeAppliedToAppEvent(app, src, evt, handles)
            arguments
                app(1,1) ma_LvdMainGUI_App
                src(1,1) AppThemer
                evt(1,1) ThemeAppliedToAppEventData
                handles struct
            end

            if(app == evt.app)
                lvdData = getappdata(app.ma_LvdMainGUI,'lvdData');
                lvdData.viewSettings.plotTrajectoryWithActiveViewProfile(handles, app);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ma_LvdMainGUI_OpeningFcn(app, varargin)
            global GLOBAL_AppThemer %#ok<GVMIS>
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to ma_LvdMainGUI (see VARARGIN)

            app.ma_LvdMainGUI.Visible = 'off'; drawnow;
            centerUIFigure(app.ma_LvdMainGUI);
            applySelectedThemeToApp(app);
            
            % appThemer = AppThemer(app);
            % appThemer.themeApp();
            % themeApp(app);

            % Choose default command line output for ma_LvdMainGUI
            try
                app.BusyStatusIcon.Visible = 'on';
                app.BusyStatusLabel.Text = sprintf('Initializing...');
                drawnow nocallbacks;

                handles.output = hObject;
                
                app.gridLayout23Resizer  = UIGridLayoutMouseDragResizer(app.ma_LvdMainGUI, app.GridLayout23);
                app.gridLayout3Resizer = UIGridLayoutMouseDragResizer(app.ma_LvdMainGUI, app.GridLayout3);
                app.gridLayout7Resizer = UIGridLayoutMouseDragResizer(app.ma_LvdMainGUI, app.GridLayout7);
                app.gridLayout4Resizer = UIGridLayoutMouseDragResizer(app.ma_LvdMainGUI, app.GridLayout4);

                hManager = uigetmodemanager(hObject);
                [hManager.WindowListenerHandles.Enabled] = deal(false);

                celBodyData = varargin{1};
                if(not(isa(celBodyData,'CelestialBodyData')))
                    celBodyData = CelestialBodyData(celBodyData);
                end
                setappdata(hObject,'celBodyData',celBodyData);

                setappdata(hObject,'current_save_location','');
                setappdata(hObject,'application_title','KSPTOT Launch Vehicle Designer');
                setappdata(hObject,'undoRedo',LVD_UndoRedoStateSet());

                lvdData = LvdData.getDefaultLvdData(celBodyData);
                setappdata(handles.ma_LvdMainGUI,'lvdData',lvdData);

                output_text_max_line_length = 1E99;
                setappdata(hObject,'output_text_max_line_length',output_text_max_line_length);
                writeOutput = @(str,type) writeToMAOutput(handles.outputText, str, type, output_text_max_line_length);
                setappdata(hObject,'write_to_output_func',writeOutput);

                timeSliderCb = @(src,evt) timeSliderStateChanged(src,evt, lvdData, handles, app);
                app.DispAxesTimeSlider.ValueChangingFcn = timeSliderCb;

                setappdata(app.DispAxesTimeSlider,'lastTime',NaN);

                initializeOutputWindowText(app, handles, app.outputText);
                view(handles.dispAxes,3);

                %These seem to be bugged in R2021a.  They cause the
                %zooming, panning, and rotating to not work on part of the
                %axes.
                %UPDATE Only seems to be an issue on the base R2021a and
                %not Update 2+
                hRot3D = rotate3d(app.dispAxes);
                hRot3D.ActionPostCallback = @(src,evt) recordFinalAxesPanZoomAfterRotation(app, src,evt, handles);
                hRot3D.ButtonDownFilter = @(src,evt) rotatePanZoomButtonDownFilter(app, src, evt);

                hZoom = zoom(handles.ma_LvdMainGUI);
                hZoom.ActionPostCallback = @(src,evt) recordFinalAxesPanZoomAfterRotation(app, src,evt, handles);
                hZoom.ButtonDownFilter = @(src,evt) rotatePanZoomButtonDownFilter(app, src, evt);

                hPan = pan(handles.ma_LvdMainGUI);
                hPan.ActionPostCallback = @(src,evt) recordFinalAxesPanZoomAfterRotation(app, src,evt, handles);
                hPan.ButtonDownFilter = @(src,evt) rotatePanZoomButtonDownFilter(app, src, evt);

                addlistener(handles.dispAxes,'CameraPosition','PostSet', @(src,evt) recordFinalAxesPanZoomAfterRotation(app, src,evt, handles));
                addlistener(handles.dispAxes,'CameraTarget','PostSet', @(src,evt) recordFinalAxesPanZoomAfterRotation(app, src,evt, handles));
                addlistener(handles.dispAxes,'CameraUpVector','PostSet', @(src,evt) recordFinalAxesPanZoomAfterRotation(app, src,evt, handles));
                addlistener(handles.dispAxes,'CameraViewAngle','PostSet', @(src,evt) recordFinalAxesPanZoomAfterRotation(app, src,evt, handles));
                addlistener(handles.dispAxes,'View','PostSet', @(src,evt) recordFinalAxesPanZoomAfterRotation(app, src,evt, handles));

                setDeleteButtonEnable(app, lvdData, handles);
                setNonSeqDeleteButtonEnable(app, lvdData, handles);

                gravSystemUpdateCbFh = @(src,evt) gravSystemUpdateCallback(app, src,evt, handles);
                appOptions = getAppOptionsFromFile();
                addlistener(appOptions.ksptot,'GravParamTypeUpdated',gravSystemUpdateCbFh);

                addlistener(lvdData.script, 'EventPropagationStarted', @(src,evt) app.handleEvtPropagationStarted(src, evt));
                addlistener(lvdData.script, 'ScriptPropagationStarted', @(src,evt) app.handleScriptPropagationStarted(src, evt));
                addlistener(lvdData.script, 'ScriptPropagationFinished', @(src,evt) app.handleScriptPropagationEnded(src, evt));
                addlistener(app, 'GenericStatusLabelUpdate', @(src,evt) app.handleGenericStatusLabelUpdateEvent(src, evt));

                addlistener(GLOBAL_AppThemer, 'ThemeAppliedToApp', @(src,evt) app.handleThemeAppliedToAppEvent(src, evt, handles));
                
                app.warnErrInvisibleAxes = axes(figure('Visible','off'));
                app.warnErrTooltipPanel = uipanel("Parent", app.ma_LvdMainGUI, "Title",'', 'Position', [0 0 1 1], 'Scrollable',"off", 'Visible','off');
                grid = uigridlayout("Parent",app.warnErrTooltipPanel, "ColumnWidth","1x", "RowHeight","1x", "ColumnSpacing",0, "RowSpacing",0, "Padding",[0,0,0,0], 'Scrollable',"off");
                app.warnErrTooltipLabel = uilabel("Parent", grid, "Text",'', "WordWrap","off", ...
                                                  "FontName",'Helvetica', "FontSize",12, "FontWeight",'normal',...
                                                  "BackgroundColor",[1,1,1]);
                
                enableDisableArrowButtons(app, lvdData, handles);

                axis(app.dispAxes,'equal');

                app.dispAxes.Toolbar.Visible = 'off';
                cameratoolbar("SetCoordSys",'none');

                app.ma_LvdMainGUI.Visible = 'on';

                runScript(app, handles, lvdData, 1);
                app.processData(handles);

                % Update handles structure
                guidata(hObject, handles);             

                app.BusyStatusIcon.Visible = 'off';
                app.BusyStatusLabel.Text = sprintf('');
                drawnow nocallbacks;

                % UIWAIT makes ma_LvdMainGUI wait for user response (see UIRESUME)
                % uiwait(handles.ma_LvdMainGUI);
            catch ME
                beep;

                errordlg('An error occured while opening Launch Vehicle Designer.  Please see the ksptot.log for more details.','Error');
%                 disp(ME.message);

                msgText = getReport(ME, 'extended','hyperlinks','off');
                disp(msgText);

                close(handles.ma_LvdMainGUI);
            end
        end

        % Menu selected function: adjustVariablesMenu
        function adjustVariablesMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to adjustVariablesMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            lvdOpt = lvdData.optimizer;
            varSet = lvdOpt.vars;
            varSet.sortVarsByEvtNum();
            [x, ~, ~, ~] = varSet.getTotalScaledXVector();

            if(~isempty(x))
                addUndoState(app, handles,'Adjust Optimization Variables');

                propScriptFcn = @() propagateScript(app, handles, lvdData, 1);
                procDataFcn = @() app.processData(handles);

                %                 lvd_adjustOptVarGUI(lvdData, propScriptFcn, procDataFcn);

                output = AppDesignerGUIOutput({false});
                lvd_adjustOptVarGUI_App(lvdData, propScriptFcn, procDataFcn, output);

                runScript(app, handles, lvdData, 1);
                app.processData(handles);
            else
                warndlg('Cannot display adjustment dialog: no optimization variables are enabled on this script.');
            end
        end

        % Menu selected function: advanceScriptToSelectedEventMenu
        function advanceScriptToSelectedEventMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to advanceScriptToSelectedEventMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            if(not(isempty(app.scriptListbox.Value)))
                event = app.scriptListbox.Value;
                eventNum = event.getEventNum();
                eventFirstStateLogEntry = lvdData.stateLog.getFirstStateLogForEvent(event);

                tf = false;
                badEvtStrs = [];
                for(i=eventNum+1:lvdData.script.getTotalNumOfEvents())
                    evt = lvdData.script.getEventForInd(i);

                    for(j=1:eventNum)
                        evtToBeDel = lvdData.script.getEventForInd(j);

                        tf = tf || evt.usesEvent(evtToBeDel);

                        if(evt.usesEvent(evtToBeDel))
                            badEvtStrs{end+1} = sprintf('Event %u uses Event %u in an action or event termination condition.', evt.getEventNum(), evtToBeDel.getEventNum()); %#ok<AGROW>
                        end
                    end
                end

                if(tf == false)
                    addUndoState(app, handles,'Advance Script to Event');

                    oldAutoPropSetting = lvdData.settings.autoPropScript;
                    if(oldAutoPropSetting == false)
                        propagateScript(app, handles, lvdData, 1);
                    end

                    lvdData.initStateModel.setInitialStateFromStateLogEntry(eventFirstStateLogEntry);

                    for(i=eventNum-1:-1:1)
                        evt = lvdData.script.getEventForInd(i);
                        lvdData.optimizer.constraints.removeConstraintsThatUseEvent(evt);
                        lvdData.optimizer.objFcn.removeObjFcnsThatUseEvent(evt);
                        lvdData.script.removeEvent(evt);
                    end

                    runScript(app, handles, lvdData, 1);
                    app.processData(handles);
                    app.scriptListbox.Value = app.scriptListbox.ItemsData(1);
                else
                    badEvtStrJoin = strjoin(badEvtStrs,'\n');
                    msgStr = sprintf('Cannot advance to event because one or more of the events that would be deleted are in use elsewhere:\n\n%s', badEvtStrJoin);
                    errordlg(msgStr,'Cannot Advance to Event');
                end
            end
        end

        % Menu selected function: astroCalculatorsMenu
        function astroCalculatorsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to astroCalculatorsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            ma_AstroCalculatorsGUI_App(lvdData.celBodyData);
        end

        % Menu selected function: autopropagateMenu
        function autopropagateMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to autopropagateMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');

            addUndoState(app, handles,'Edit Script Auto-Propagation');

            if(strcmp(get(gcbo, 'Checked'),'on'))
                lvdData.settings.autoPropScript = false;

                writeOutput('Script auto-propagation is off.  Run script manually with ctrl-p.','append');
            else
                lvdData.settings.autoPropScript = true;

                writeOutput('Script auto-propagation is on.','append');
            end
        end

        % Menu selected function: celBodyCatalogMenu
        function celBodyCatalogMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to celBodyCatalogMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            %     ma_CelBodyCatalogGUI(lvdData.celBodyData);
            ma_CelBodyCatalogGUI_App(lvdData.celBodyData);
        end

        % Menu selected function: clearOutputMenu
        function clearOutputMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to clearOutputMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            initializeOutputWindowText(app, handles, app.outputText);
        end

        % Menu selected function: configureCelestialBodyStateCacheMenu
        function configureCelestialBodyStateCacheMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to configureCelestialBodyStateCacheMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            configureCelBodyStateCacheGUI_App(lvdData.celBodyData);
        end

        % Menu selected function: copyDurationOfSelEventMenu
        function copyDurationOfSelEventMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to copyDurationOfSelEventMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            if(not(isempty(app.scriptListbox.Value)))
                eventNum = app.scriptListbox.Value.getEventNum();
                stateLog = lvdData.stateLog.getMAFormattedStateLogMatrix(false);

                eventLog = stateLog(stateLog(:,13)==eventNum,:);
                duration = max(eventLog(:,1)) - min(eventLog(:,1));

                clipboard('copy', fullAccNum2Str(duration));
            end
        end

        % Menu selected function: copyOrbitAfterSelectedEventMenu
        function copyOrbitAfterSelectedEventMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to copyOrbitAfterSelectedEventMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            celBodyData = getappdata(handles.ma_LvdMainGUI,'celBodyData');

            if(not(isempty(app.scriptListbox.Value)))
                eventNum = app.scriptListbox.Value.getEventNum();
                stateLog = lvdData.stateLog.getMAFormattedStateLogMatrix(false);

                state = stateLog(stateLog(:,13)==eventNum,:);
                state = state(end,:);

                bodyID = state(8);

                bodyInfo = getBodyInfoByNumber(bodyID, celBodyData);
                gmu = bodyInfo.gm;
                utSec = state(1);
                rVect = state(2:4)';
                vVect = state(5:7)';

                [sma, ecc, inc, raan, arg, tru] = getKeplerFromState(rVect,vVect,gmu);
                clipboardData = [utSec, sma, ecc, inc, raan, arg, tru, bodyID];
                set(handles.copyOrbitAfterSelectedEventMenu,'UserData',clipboardData);
                copyOrbitToClipboardFromStateLog([],[],handles.copyOrbitAfterSelectedEventMenu);
            end
        end

        % Menu selected function: copyUtAtEndOfSelEventMenu
        function copyUtAtEndOfSelEventMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to copyUtAtEndOfSelEventMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            if(not(isempty(app.scriptListbox.Value)))
                eventNum = app.scriptListbox.Value.getEventNum();
                stateLog = lvdData.stateLog.getMAFormattedStateLogMatrix(false);

                state = stateLog(stateLog(:,13)==eventNum,:);
                state = state(end,:);

                clipboard('copy', fullAccNum2Str(state(1,1)));
            end
        end

        % Menu selected function: copyUtAtStartOfSelEventMenu
        function copyUtAtStartOfSelEventMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to copyUtAtStartOfSelEventMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            if(not(isempty(app.scriptListbox.Value)))
                eventNum = app.scriptListbox.Value.getEventNum();
                stateLog = lvdData.stateLog.getMAFormattedStateLogMatrix(false);

                state = stateLog(stateLog(:,13)==eventNum,:);
                state = state(1,:);

                clipboard('copy', fullAccNum2Str(state(1,1)));
            end
        end

        % Menu selected function: createContConstraintsWithSelEvtMenu
        function createContConstraintsWithSelEvtMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to createContConstraintsWithSelEvtMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            totNumEvts = lvdData.script.getTotalNumOfEvents();

            if(not(isempty(app.scriptListbox.Value)))
                if(totNumEvts >= 2)
                    eventNum = app.scriptListbox.Value.getEventNum();
                    evt = lvdData.script.getEventForInd(eventNum);

                    addUndoState(app, handles,'Create Continuity Constraints');

                    output = AppDesignerGUIOutput({false});
                    lvd_CreateContinuityConstraintsGUI_App(lvdData, evt, output);
                    constCreated = output.output{1};

                    if(constCreated)
                        uialert(app.ma_LvdMainGUI, 'Continuity constraints added successfully.', "Continuity Constraints", "Icon",'success');
                    end
                else
                    warndlg('There must be at least one other event in the missions script to create continuity constraints with.');
                end
            end
        end

        % Menu selected function: createkOSExecCodeMenu
        function createkOSExecCodeMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to createkOSExecCodeMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            [FileName,PathName,FilterIndex] = uiputfile({'*.csv','CSV Files'},'Save LVD Data to CSV.');

            if(FilterIndex == 0)
                return;
            else
                csvFilePath = fullfile(PathName,FileName);
            end

            %select events to put into the file
            eventsListboxStr = lvdData.script.getListboxStr();
            evtInds = 1:length(eventsListboxStr);
            out = AppDesignerGUIOutput();
            listdlgARH_App('ListString', eventsListboxStr, ...
                'ListSize', [300, 300], ...
                'InitialValue', evtInds, ...
                'Name', 'Select Events for Export', ...
                'PromptString', 'Select the events to export for kOS:', ...
                'out',out);
            Selection = out.output{1};
            ok = out.output{2};

            if(ok == 0 || isempty(Selection))
                return;
            end

            oldAutoPropSetting = lvdData.settings.autoPropScript;
            if(oldAutoPropSetting == false)
                propagateScript(app, handles, lvdData, 1);
            end

            stateLog = lvdData.stateLog;
            stateLogEntries = LaunchVehicleStateLogEntry.empty(1,0);
            for(i=1:length(Selection))
                evt = lvdData.script.getEventForInd(Selection(i));
                selEvts(i) = evt; %#ok<AGROW> 
                selEvtNames{i} = evt.getListboxStr(); %#ok<AGROW> 
                stateLogEntries = [stateLogEntries, stateLog.getAllStateLogEntriesForEvent(evt)]; %#ok<AGROW>
            end

            %select events to stage at the start of.
            out = AppDesignerGUIOutput();
            listdlgARH_App('ListString', selEvtNames, ...
                'ListSize', [300, 300], ...
                'InitialValue', [], ...
                'Name', 'Select Events for Export', ...
                'PromptString', 'Trigger a Staging command at  the start of the selected events:', ...
                'out',out);
            StagingSelection = out.output{1};
            ok = out.output{2};

            if(ok == 0)
                stagingEvts = LaunchVehicleEvent.empty(1,0);
            else
                stagingEvts = selEvts(StagingSelection);
            end

            %sort state log entries by time ascending so that they show up in the
            %right order.
            stateLogTimes = [stateLogEntries.time];
            [~,I] = sort(stateLogTimes);
            stateLogEntries = stateLogEntries(I);

            numEntries = length(stateLogEntries);

            time = NaN(1,numEntries);
            yaw = NaN(1,numEntries);
            pitch = NaN(1,numEntries);
            roll = NaN(1,numEntries);
            throttle = NaN(1,numEntries);
            timeToNextEvt = NaN(1,numEntries);
            stagingCues = NaN(1,numEntries);
            sma = NaN(1,numEntries);
            ecc = NaN(1,numEntries);
            inc = NaN(1,numEntries);
            raan = NaN(1,numEntries);
            arg = NaN(1,numEntries);
            tru = NaN(1,numEntries);
            evtNames = cell(1,numEntries);
            nextEvtNames = cell(1,numEntries);

            hPrgDlg = uiprogressdlg(app.ma_LvdMainGUI, "Message",'Generating kOS control file, please wait...', "ShowPercentage","on", "Title","kOS Control File");

            numStateLogEntries = length(stateLogEntries);
            for(i=1:numStateLogEntries)
                stateLogEntry = stateLogEntries(i);

                frame = stateLogEntry.centralBody.getBodyFixedFrame();

                time(i) = stateLogEntry.time;
                yaw(i) = lvd_SteeringAngleTask(stateLogEntry, 'yaw', frame);
                pitch(i) = lvd_SteeringAngleTask(stateLogEntry, 'pitch', frame);
                roll(i) = lvd_SteeringAngleTask(stateLogEntry, 'roll', frame);
                throttle(i) = stateLogEntry.throttle;

                ke = stateLogEntry.getCartesianElementSetRepresentation(false).convertToKeplerianElementSet();
                sma(i) = ke.sma;
                ecc(i) = ke.ecc;
                inc(i) = rad2deg(ke.inc);
                raan(i) = rad2deg(ke.raan);
                arg(i) = rad2deg(ke.arg);
                tru(i) = rad2deg(ke.tru);

                evt = stateLogEntry.event;
                evtNames{i} = evt.name;

                nextEvt = LaunchVehicleEvent.empty(0,1);
                nextEvtStartTime = [];
                for(j=i:numStateLogEntries)
                    testEntry = stateLogEntries(j);
                    if(evt ~= testEntry.event)
                        nextEvt = testEntry.event;
                        nextEvtStartTime = testEntry.time;
                        break;
                    end
                end

                if(not(isempty(nextEvt)))
                    timeToNextEvt(i) = nextEvtStartTime - stateLogEntry.time;
                    nextEvtNames{i} = nextEvt.name;
                else
                    timeToNextEvt(i) = 0;
                    nextEvtNames{i} = '';
                end

                if(ismember(evt, stagingEvts))
                    firstEntryForEvt = lvdData.stateLog.getFirstStateLogForEvent(evt);
                    if(firstEntryForEvt.time == stateLogEntry.time)
                        stagingCues(i) = 1;
                    else
                        stagingCues(i) = 0;
                    end
                else
                    stagingCues(i) = 0;
                end

                hPrgDlg.Value = i/numStateLogEntries;
            end

            %we need to find all the times where a staging cue is 1 and
            %make sure that all staging cues for that time are 1.
            stagingCues(ismember(time, unique(time(stagingCues == 1)))) = 1;

            if(isvalid(hPrgDlg))
                close(hPrgDlg);
            end

            M = [time(:)'; ...
                yaw(:)'; ...
                pitch(:)'; ...
                roll(:)'; ...
                throttle(:)'; ...
                timeToNextEvt(:)'; ...
                stagingCues(:)'; ...
                sma(:)'; ...
                ecc(:)'; ...
                inc(:)'; ...
                raan(:)'; ...
                arg(:)'; ...
                tru(:)'];
            dlmwrite(csvFilePath,M,'precision','%0.13f');

            fid = fopen(csvFilePath,'a');
            fprintf(fid, '%s\n', strjoin(evtNames,','));
            fprintf(fid, '%s', strjoin(nextEvtNames,','));
            fclose(fid);

            msg = sprintf('kOS Control file written to: \n\n%s',csvFilePath);
            uialert(app.ma_LvdMainGUI, msg, 'kOS Control File', 'Icon','success', 'Modal',true);
        end

        % Button pushed function: decrOrbitToPlotNum
        function decrOrbitToPlotNum_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to decrOrbitToPlotNum (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %     orbitNumToPlot = get(handles.dispAxes,'UserData');
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            orbitNumToPlot = lvdData.viewSettings.selViewProfile.orbitNumToPlot;

            if(orbitNumToPlot > 1)
                orbitNumToPlot = orbitNumToPlot - 1;

            elseif(orbitNumToPlot == 1)
                chunkedStateLog = lvdData.stateLog.breakUpStateLogBySoIChunk();
                orbitNumToPlot = size(chunkedStateLog,1);
            end

            lvdData.viewSettings.selViewProfile.orbitNumToPlot = orbitNumToPlot;
            set(handles.dispAxes,'UserData',orbitNumToPlot);
            app.processData(handles);
        end

        % Button pushed function: deleteEvent
        function deleteEvent_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to deleteEvent (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            if(not(isempty(app.scriptListbox.Value)))
                eventNum = app.scriptListbox.Value.getEventNum();
                evt = lvdData.script.getEventForInd(eventNum);

                tfEvt = evt.usesEvent(evt);
                tfObj = lvdData.optimizer.objFcn.usesEvent(evt);
                if(tfEvt || tfObj)
                    warndlg(sprintf('Could not delete the event "%s" because it is in use as part of an event action or objective function.  Remove the event dependencies before attempting to delete the event.', evt.getListboxStr()),'Cannot Delete Event','modal');
                else
                    addUndoState(app, handles,'Delete Event');

                    lvdData.optimizer.vars.removeVariablesThatUseEvent(evt, lvdData);
                    lvdData.optimizer.constraints.removeConstraintsThatUseEvent(evt);
                    lvdData.viewSettings.removeEventFromListOfPlottedEvents(evt);

                    lvdData.script.removeEventFromIndex(eventNum);

                    if(eventNum > length(lvdData.script.evts))
                        app.scriptListbox.Value = lvdData.script.getEventForInd(length(lvdData.script.evts));
                    else
                        app.scriptListbox.Value = lvdData.script.getEventForInd(eventNum);
                    end
                    
                    setDeleteButtonEnable(app, lvdData, handles);

                    runScript(app, handles, lvdData, 1);
                    app.processData(handles);
                end
            end
        end

        % Button pushed function: deleteNonSeqEventButton
        function deleteNonSeqEventButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to deleteNonSeqEventButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            if(not(isempty(app.nonSeqEventsListbox.Value)))
                eventNum = find(app.nonSeqEventsListbox.Value == app.nonSeqEventsListbox.ItemsData);

                addUndoState(app, handles,'Delete Non-Sequential Event');
                lvdData.script.nonSeqEvts.removeEventFromIndex(eventNum);

                if(lvdData.script.nonSeqEvts.getTotalNumOfEvents() > 0)
                    if(eventNum > lvdData.script.nonSeqEvts.getTotalNumOfEvents())
                        app.nonSeqEventsListbox.Value = lvdData.script.nonSeqEvts.getEventForInd(lvdData.script.nonSeqEvts.getTotalNumOfEvents());
                    else
                        app.nonSeqEventsListbox.Value = lvdData.script.nonSeqEvts.getEventForInd(eventNum);
                    end
                end

                setNonSeqDeleteButtonEnable(app, lvdData, handles)

                runScript(app, handles, lvdData, 1);
                app.processData(handles);
            end
        end

        % Menu selected function: displayEvtPropTimesInLogFileMenu
        function displayEvtPropTimesInLogFileMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to displayEvtPropTimesInLogFileMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');

            addUndoState(app, handles,'Edit Display Event Prop Times');

            if(strcmp(get(gcbo, 'Checked'),'on'))
                lvdData.settings.disEvtPropTimes = false;

                writeOutput('Display of event propagation times in KSPTOT log file is off.','append');
            else
                lvdData.settings.disEvtPropTimes = true;

                writeOutput('Event propagation times will be displayed in the ksptot.log file.','append');
            end
        end

        % Menu selected function: editAnglesMenu
        function editAnglesMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editAnglesMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            addUndoState(app, handles,'Edit Angles');
            lvd_EditGeometricAnglesGUI_App(lvdData);

            runScript(app, handles, lvdData, 1);
            app.processData(handles);
        end

        % Menu selected function: editCalculusObjectsMenu
        function editCalculusObjectsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editCalculusObjectsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            addUndoState(app, handles,'Edit Calculus Calculations');
            lvd_EditCalculusObjsGUI_App(lvdData);

            runScript(app, handles, lvdData, 1);
            app.processData(handles);
        end

        % Menu selected function: editConstraintsMenu
        function editConstraintsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editConstraintsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            addUndoState(app, handles,'Edit Constraints');

            lvd_EditConstraintsGUI_App(lvdData);

            runScript(app, handles, lvdData, 1);
            app.processData(handles);
        end

        % Menu selected function: editCoordSysMenu
        function editCoordSysMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editCoordSysMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            addUndoState(app, handles,'Edit Coordinate Systems');
            lvd_EditGeometricCoordSysGUI_App(lvdData);

            runScript(app, handles, lvdData, 1);
            app.processData(handles);
        end

        % Menu selected function: editExtremaMenu
        function editExtremaMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editExtremaMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            addUndoState(app, handles,'Edit Extrema');
            lvd_EditExtremasGUI_App(lvdData);

            runScript(app, handles, lvdData, 1);
            app.processData(handles);
        end

        % Menu selected function: editGroundObjsMenu
        function editGroundObjsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editGroundObjsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            addUndoState(app, handles,'Edit Ground Objects');

            lvd_EditGroundObjectsGUI_App(lvdData.groundObjs);

            app.processData(handles);
        end

        % Menu selected function: editInitialStateMenu
        function editInitialStateMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editInitialStateMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            addUndoState(app, handles,'Edit Initial State');

            output = AppDesignerGUIOutput({false});
            lvd_EditInitialStateGUI_App(lvdData, output);

            runScript(app, handles, lvdData, 1);
            app.processData(handles);
        end

        % Menu selected function: editLaunchVehicleMenu
        function editLaunchVehicleMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editLaunchVehicleMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            addUndoState(app, handles,'Edit Launch Vehicle');

            %             lvd_editLaunchVehicle(lvdData);
            lvd_editLaunchVehicle_App(lvdData);

            runScript(app, handles, lvdData, 1);
            app.processData(handles);
        end

        % Menu selected function: editMenu
        function editMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            %             hObject = handles.editMenu;

            % hObject    handle to editMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            undoRedo = getappdata(handles.ma_LvdMainGUI,'undoRedo');
            [undoTf, undoActionName] = undoRedo.shouldUndoMenuBeEnabled();
            [redoTf, redoActionName] = undoRedo.shouldRedoMenuBeEnabled();

            if(undoTf)
                set(handles.undoMenu,'Enable','on');
            else
                set(handles.undoMenu,'Enable','off');
            end
            set(handles.undoMenu,'Label',['Undo ',undoActionName]);

            if(redoTf)
                set(handles.redoMenu,'Enable','on');
            else
                set(handles.redoMenu,'Enable','off');
            end
            set(handles.redoMenu,'Label',['Redo ',redoActionName]);
        end

        % Menu selected function: editMissionNotesMenu
        function editMissionNotesMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editMissionNotesMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            addUndoState(app, handles,'Edit Mission Notes');

            %             lvd_MissionNotesGUI(lvdData);
            lvd_MissionNotesGUI_App(lvdData);
        end

        % Menu selected function: editObjFunctionMenu
        function editObjFunctionMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editObjFunctionMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            addUndoState(app, handles,'Edit Objective Function');

            %     lvd_EditObjectiveFunctionGUI(lvdData);
            %             lvd_EditCompositeObjectiveFunctionGUI(lvdData);
            output = AppDesignerGUIOutput({false});
            lvd_EditCompositeObjectiveFunctionGUI_App(lvdData, output)
        end

        % Menu selected function: editPlanesMenu
        function editPlanesMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editPlanesMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            addUndoState(app, handles,'Edit Planes');
            lvd_EditGeometricPlanesGUI_App(lvdData);

            runScript(app, handles, lvdData, 1);
            app.processData(handles);
        end

        % Menu selected function: editPointsMenu
        function editPointsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editPointsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            addUndoState(app, handles,'Edit Points');
            lvd_EditGeometricPointsGUI_App(lvdData);

            runScript(app, handles, lvdData, 1);
            app.processData(handles);
        end

        % Menu selected function: editRefRamesMenu
        function editRefRamesMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editRefRamesMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            addUndoState(app, handles,'Edit Reference Frames');
            lvd_EditGeometricRefFramesGUI_App(lvdData);

            runScript(app, handles, lvdData, 1);
            app.processData(handles);
        end

        % Menu selected function: editStopwatchesMenu
        function editStopwatchesMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editStopwatchesMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            addUndoState(app, handles,'Edit Stopwatches');

            lvd_EditStopwatchesGUI_App(lvdData);

            runScript(app, handles, lvdData, 1);
            app.processData(handles);
        end

        % Menu selected function: editVectorsMenu
        function editVectorsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editVectorsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            addUndoState(app, handles,'Edit Vectors');
            lvd_EditGeometricVectorsGUI_App(lvdData);

            runScript(app, handles, lvdData, 1);
            app.processData(handles);
        end

        % Menu selected function: editViewSettingsMenu
        function editViewSettingsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editViewSettingsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            addUndoState(app, handles,'Edit View Settings');

            hPrgDlg = uiprogressdlg(app.ma_LvdMainGUI,"Indeterminate","on","Message","Loading View Settings...", "Title",'Launch Vehicle Designer');
            lvd_viewSettingsGUI_App(lvdData.viewSettings);
            close(hPrgDlg);
               
            setappdata(app.DispAxesTimeSlider,'lastTime',NaN);

            lvdData.validation.clearOutputs();
            lvdData.validation.validate();
            updateWarnErrorLabels(app, handles, true);

            enableDisableArrowButtons(app, lvdData, handles);
            app.processData(handles);
        end

        % Menu selected function: exitMenu
        function exitMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to exitMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.ma_LvdMainGUI);
        end

        % Menu selected function: fileMenu
        function fileMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to fileMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            appOptions = getAppOptionsFromFile();
            recentMsnCaseFilesStr = appOptions.ksptot.lvdrecentmsncasefiles;
            recentMissions = strsplit(recentMsnCaseFilesStr, ';');

            delete(handles.openRecentMissionPlansMenu.Children);

            if(numel(recentMsnCaseFilesStr) > 0)
                for(i=1:numel(recentMissions))
                    missionFileName = recentMissions{i};
                    if(isempty(missionFileName) || exist(missionFileName,'file') == 0)
                        continue;
                    end
                    [path,name,ext] = fileparts(missionFileName);

                    if(length(path) > 33)
                        subPath = sprintf('%s...%s',path(1:30),filesep);
                    else
                        subPath = sprintf('%s%s', path, filesep);
                    end

                    menuName = sprintf('%s%s%s', subPath, name, ext);

                    callback = @(src,evt) recentMissionCallback(app, src,evt, missionFileName, handles);
                    uimenu(handles.openRecentMissionPlansMenu, 'Text',menuName, 'MenuSelectedFcn', callback);
                end

            else
                uimenu(handles.openRecentMissionPlansMenu, 'Text','No Recent Missions', 'Enable','off');
            end
        end

        % Menu selected function: graphicalAnalysisMenu
        function graphicalAnalysisMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to graphicalAnalysisMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            %     lvd_GraphicalAnalysisGUI(lvdData, handles.ma_LvdMainGUI);

            %             hFig = msgbox('Starting Graphical Analysis, please wait...', 'Graphical Analysis', 'help');
            addUndoState(app, handles,'Edit Graphical Analysis');

            d = uiprogressdlg(app.ma_LvdMainGUI, ...
                "Icon","info", ...
                "Message",'Starting Graphical Analysis, please wait...', ...
                "Indeterminate","on", ...
                "Title",'Graphical Analysis');
            lvd_GraphicalAnalysisGUI_App(lvdData, handles.ma_LvdMainGUI);
            close(d);
        end

        % Menu selected function: haloOrbitConstructorMenu
        function haloOrbitConstructorMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to haloOrbitConstructorMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            lvd_HaloOrbitConstructor_App(lvdData.celBodyData);
        end

        % Button pushed function: incrOrbitToPlotNum
        function incrOrbitToPlotNum_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to incrOrbitToPlotNum (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %     orbitNumToPlot = get(handles.dispAxes,'UserData');
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
%             maStateLog = lvdData.stateLog.getMAFormattedStateLogMatrix(false);
%             chunkedStateLog = breakStateLogIntoSoIChunks(maStateLog);
            chunkedStateLog = lvdData.stateLog.breakUpStateLogBySoIChunk();
            orbitNumToPlot = lvdData.viewSettings.selViewProfile.orbitNumToPlot;

            if(orbitNumToPlot < size(chunkedStateLog,1))
                orbitNumToPlot = orbitNumToPlot + 1;

            elseif(orbitNumToPlot == size(chunkedStateLog,1))
                orbitNumToPlot = 1;

            elseif(orbitNumToPlot > size(chunkedStateLog,1))
                orbitNumToPlot = size(chunkedStateLog,1);
            end

            lvdData.viewSettings.selViewProfile.orbitNumToPlot = orbitNumToPlot;
            set(handles.dispAxes,'UserData',orbitNumToPlot);
            app.processData(handles);
        end

        % Button pushed function: insertEventButton
        function insertEventButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to insertEventButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            addUndoState(app, handles,'Insert Event');

            event = LaunchVehicleEvent.getDefaultEvent(lvdData.script);

            output = AppDesignerGUIOutput({false});
            lvd_editEventGUI_App(event, false, output);
            useEvt = output.output{1};

            if(useEvt)
                selEvtNum = find(app.scriptListbox.ItemsData == app.scriptListbox.Value,1,"first");
                lvdData.script.addEventAtInd(event,selEvtNum);

                setDeleteButtonEnable(app, lvdData, handles);

                runScript(app, handles, lvdData, 1);
                app.processData(handles);
            end
        end

        % Button pushed function: insertNonSeqEventButton
        function insertNonSeqEventButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to insertNonSeqEventButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            addUndoState(app, handles,'Insert Non-Sequential Event');

            selEvtNum = get(handles.nonSeqEventsListbox,'Value');
            event = LaunchVehicleEvent.getDefaultEvent(lvdData.script);
            nonSeqEvt = LaunchVehicleNonSeqEvent(event);
            lvdData.script.nonSeqEvts.addEventAtInd(nonSeqEvt,selEvtNum);
            lvd_editNonSeqEventGUI_App(nonSeqEvt, lvdData);

            setNonSeqDeleteButtonEnable(app, lvdData, handles)

            runScript(app, handles, lvdData, 1);
            app.processData(handles);
        end

        % Menu selected function: intMaxSimTimeMenu
        function intMaxSimTimeMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to intMaxSimTimeMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');

            input_str = sprintf(['Enter the desired maximum simulation time (sec):\n',...
                '(Minimum = 0.0 sec, Maximum = Inf sec)\n', ...
                '(Script execution stops if the duration of the mission exceeds this value.)']);
            str = inputdlg(input_str, 'Maximum Simulation Time', [1 75], {fullAccNum2Str(lvdData.settings.simMaxDur)});
            if(isempty(str))
                return;
            end

            str = str{1};

            if(checkStrIsNumeric(str) && str2double(str) >= 0 && str2double(str) <= Inf)
                addUndoState(app, handles,'Set Max Simulation Time');

                writeOutput(sprintf('Setting maximum simulation time to %s sec.', str),'append');

                lvdData.settings.simMaxDur = str2double(str);

                runScript(app, handles, lvdData, 1);
                app.processData(handles);
            else
                writeOutput(sprintf('Could not set the desired maximum simulation time.  "%s" is an invalid entry.', str),'append');
                beep;
            end
        end

        % Menu selected function: intMinAltitudeMenu
        function intMinAltitudeMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to intMinAltitudeMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');

            input_str = sprintf(['Enter the desired minimum integration altitude (km):\n',...
                '(Minimum = -Inf km, Maximum = 0 km)\n', ...
                '(Script execution stops if the vehicle reaches this altitude.)']);
            str = inputdlg(input_str, 'Minimum Integration Altitude', [1 75], {fullAccNum2Str(lvdData.settings.minAltitude)});
            if(isempty(str))
                return;
            end

            str = str{1};

            if(checkStrIsNumeric(str) && str2double(str) >= -Inf && str2double(str) <= 0.0)
                addUndoState(app, handles,'Edit Minimum Altitude');

                writeOutput(sprintf('Setting minimum integration altitude to %s km.', str),'append');

                lvdData.settings.minAltitude = str2double(str);

                runScript(app, handles, lvdData, 1);
                app.processData(handles);
            else
                writeOutput(sprintf('Could not set the desired minimum integration altitude.  "%s" is an invalid entry.', str),'append');
                beep;
            end
        end

        % Menu selected function: integrationSettingsMenu
        function integrationSettingsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to integrationSettingsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            isSparseOutput = lvdData.settings.isSparseOutput;
            if(isSparseOutput==true)
                set(handles.sparseIntegratorOutputMenu, 'Checked', 'on');
            else
                set(handles.sparseIntegratorOutputMenu, 'Checked', 'off');
            end
        end

        % Close request function: ma_LvdMainGUI
        function ma_LvdMainGUI_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to ma_LvdMainGUI (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: delete(hObject) closes the figure
            %             if(strcmpi(handles.plotWorkingLbl.Visible,'on'))
            %                 askToQuit = true;
            %             else
            %                 askToQuit = false;
            %             end
            askToQuit = false;

            yesStr = 'Yes';
            if(askToQuit)
                response = uiconfirm(app.ma_LvdMainGUI,'KSPTOT is still processing.  Any in-work data will be lost upon closing.  Continue?', ...
                    'Launch Vehicle Designer', ...
                    'Options',{'Yes','No'}, ...
                    'DefaultOption',yesStr);
            else
                response = yesStr;
            end

            if(~strcmpi(response,yesStr))
                return;
            else
                if(isMissionPlanSaved(app, handles))
                    askToClear = false;
                else
                    askToClear = true;
                end

                if(askToClear)
                    %                     response = questdlg(['All unsaved work will be lost.  Continue?'],'Close Launch Vehicle Designer?',yesStr,'No','No');
                    response = uiconfirm(app.ma_LvdMainGUI,'All unsaved work will be lost.  Continue?', ...
                        'Launch Vehicle Designer', ...
                        'Options',{'Yes','No'}, ...
                        'DefaultOption',yesStr);
                else
                    response = yesStr;
                end

                if(~strcmpi(response,yesStr))
                    return;
                end

                try
                    delete(app.warnErrInvisibleAxes.Parent);
                catch
                    %nothing
                end
                
                delete(hObject);
            end
        end

        % Window key press function: ma_LvdMainGUI
        function ma_LvdMainGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);

            % hObject    handle to ma_LvdMainGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            key = eventdata.Key;
            mod = eventdata.Modifier;

            if(hObject == handles.scriptListbox && ...
                    strcmpi(key,'f') && ...
                    length(mod) == 1 && ...
                    strcmpi(mod{1},'control'))

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                toggleOptimForSelEventMenu_Callback(app, evt);
            end
        end

        % Menu selected function: managePluginsMenu
        function managePluginsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to managePluginsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            addUndoState(app, handles,'Manage Plugins');

            result = ldv_editPluginGUI(lvdData);

            if(result == false)
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                undoMenu_Callback(app, evt);
            else
                runScript(app, handles, lvdData, 1);
            end

            app.processData(handles);
        end

        % Menu selected function: maxScriptPropTimeMenu
        function maxScriptPropTimeMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to maxScriptPropTimeMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');

            input_str = sprintf(['Enter the desired maximum script propagation time (sec):\n',...
                '(Minimum = 0.001 sec, Maximum = 3600 sec)\n', ...
                '(Script execution stops if the amount of time required to propagate the script exceeds this.)']);
            str = inputdlg(input_str, 'Maximum Script Execution Time', [1 75], {fullAccNum2Str(lvdData.settings.maxScriptPropTime)});
            if(isempty(str))
                return;
            end

            str = str{1};

            if(checkStrIsNumeric(str) && str2double(str) >= 0.001 && str2double(str) <= 3600)
                addUndoState(app, handles,'Edit Max Script Propagation Time');

                writeOutput(sprintf('Setting maximum script execution time to %s sec.', str),'append');

                lvdData.settings.maxScriptPropTime = str2double(str);

                runScript(app, handles, lvdData, 1);
                app.processData(handles);
            else
                writeOutput(sprintf('Could not set the desired maximum script execution time.  "%s" is an invalid entry.', str),'append');
                beep;
            end
        end

        % Button pushed function: moveEventDown
        function moveEventDown_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to moveEventDown (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            if(not(isempty(app.scriptListbox.Value)))
                addUndoState(app, handles,'Move Event Down');

                eventNum = app.scriptListbox.Value.getEventNum();
                lvdData.script.moveEvtAtIndexDown(eventNum);

                if(eventNum < length(lvdData.script.evts))
                    set(handles.scriptListbox,'Value',eventNum+1);
                end

                runScript(app, handles, lvdData, 1);
                app.processData(handles);
            end
        end

        % Button pushed function: moveEventUp
        function moveEventUp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to moveEventUp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            if(not(isempty(app.scriptListbox.Value)))
                addUndoState(app, handles,'Move Event Up');

                eventNum = app.scriptListbox.Value.getEventNum();
                lvdData.script.moveEvtAtIndexUp(eventNum);

                if(eventNum > 1)
                    set(handles.scriptListbox,'Value',eventNum-1);
                end

                runScript(app, handles, lvdData, 1);
                app.processData(handles);
            end
        end

        % Menu selected function: newMissionPlanMenu
        function newMissionPlanMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to newMissionPlanMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if(isMissionPlanSaved(app, handles))
                askToClear = false;
            else
                askToClear = true;
            end

            if(askToClear)
                %                 response = questdlg(['All unsaved work will be lost.  Continue?'],'Create new mission plan?','Yes','No','No');
                response = uiconfirm(app.ma_LvdMainGUI,'All unsaved work will be lost.  Continue?', ...
                    'Launch Vehicle Designer', ...
                    'Options',{'Yes','No'}, ...
                    'DefaultOption','Yes');
            else
                response = 'Yes';
            end

            if(~strcmpi(response,'Yes'))
                return;
            end

            setappdata(handles.ma_LvdMainGUI,'undoRedo',LVD_UndoRedoStateSet());

            % celBodyData = getappdata(handles.ma_LvdMainGUI,'celBodyData');
            % setappdata(handles.ma_LvdMainGUI,'celBodyData', celBodyData);

            % celBodyData = getappdata(handles.ma_LvdMainGUI,'celBodyData');
            write_to_output_func = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
            application_title = getappdata(handles.ma_LvdMainGUI,'application_title');
            if(askToClear)
                write_to_output_func('Creating new mission plan... ','append');
            end

            celBodyData = getappdata(handles.ma_LvdMainGUI,'celBodyData');
            lvdData = LvdData.getDefaultLvdData(celBodyData);
            setappdata(handles.ma_LvdMainGUI,'lvdData',lvdData);
            setappdata(handles.ma_LvdMainGUI,'current_save_location','');

            setDeleteButtonEnable(app, lvdData, handles);
            setNonSeqDeleteButtonEnable(app, lvdData, handles);

            setappdata(app.DispAxesTimeSlider,'lastTime',NaN);
            timeSliderCb = @(src,evt) timeSliderStateChanged(src,evt, lvdData, handles, app);
            app.DispAxesTimeSlider.ValueChangingFcn = timeSliderCb;

            enableDisableArrowButtons(app, lvdData, handles);

            addlistener(lvdData.script, 'EventPropagationStarted', @(src,evt) app.handleEvtPropagationStarted(src, evt));
            addlistener(lvdData.script, 'ScriptPropagationStarted', @(src,evt) app.handleScriptPropagationStarted(src, evt));
            addlistener(lvdData.script, 'ScriptPropagationFinished', @(src,evt) app.handleScriptPropagationEnded(src, evt));

            runScript(app, handles, lvdData, 1);
            app.processData(handles);

            cameratoolbar('ResetCamera');
            view(3);

            set(handles.ma_LvdMainGUI,'Name', application_title);

            if(askToClear)
                write_to_output_func(['Done.'],'appendSameLine'); %#ok<*NBRAK>
            end

            set(handles.newMissionPlanToolbar,'Enable','on');
            set(handles.openMissionPlanToolbar,'Enable','on');
            set(handles.saveMissionPlanToolbar,'Enable','on');
        end

        % Callback function: newMissionPlanToolbar
        function newMissionPlanToolbar_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to newMissionPlanToolbar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(hObject,'Enable','off');
            drawnow nocallbacks;
            %                 newMissionPlanMenu_Callback(handles.newMissionPlanMenu, [], handles);
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            newMissionPlanMenu_Callback(app, evt);

            set(handles.newMissionPlanToolbar,'Enable','on');
            set(handles.openMissionPlanToolbar,'Enable','on');
            set(handles.saveMissionPlanToolbar,'Enable','on');
        end

        % Value changed function: nonSeqEventsListbox
        function nonSeqEventsListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to nonSeqEventsListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns nonSeqEventsListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from nonSeqEventsListbox
            % if(strcmpi(get(handles.ma_LvdMainGUI,'SelectionType'),'open'))
                %                 addUndoState(app, handles,'Edit Non-Sequential Event');
                %
                %                 eventNum = get(hObject,'Value');
                %                 lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                %
                %                 nonSeqEvt = lvdData.script.nonSeqEvts.getEventForInd(eventNum);
                %                 lvd_editNonSeqEventGUI_App(nonSeqEvt, lvdData);
                %
                %                 runScript(app, handles, lvdData, 1);
                %                 app.processData(handles);
                % zoom(app.ma_LvdMainGUI, 'off');
                % rotate3d(app.ma_LvdMainGUI, 'off');
                % pan(app.ma_LvdMainGUI, 'off');
            % end
        end

        % Menu selected function: openMissionPlanMenu
        function openMissionPlanMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to openMissionPlanMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if(isMissionPlanSaved(app, handles))
                askToClear = false;
            else
                askToClear = true;
            end

            if(askToClear)
                response = uiconfirm(app.ma_LvdMainGUI,'All unsaved work will be lost.  Continue?', ...
                    'Launch Vehicle Designer', ...
                    'Options',{'Yes','No'}, ...
                    'DefaultOption','Yes');
            else
                response = 'Yes';
            end

            if(~strcmpi(response,'Yes'))
                return;
            end

            celBodyData = getappdata(handles.ma_LvdMainGUI,'celBodyData');
            write_to_output_func = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
            application_title = getappdata(handles.ma_LvdMainGUI,'application_title');
            current_save_location = getappdata(handles.ma_LvdMainGUI,'current_save_location');

            if(isempty(current_save_location))
                fileNameToDefaultTo = 'mission.mat';
            else
                [current_save_location_path,~,~] = fileparts(current_save_location);
                fileNameToDefaultTo = [current_save_location_path,filesep,'mission.mat'];
            end

            [FileName,PathName] = uigetfile({'*.mat','KSPTOT Launch Vehicle Designer Case File (*.mat)'},...
                'Open Launch Vehicle Designer Case',...
                fileNameToDefaultTo);
            filePath = [PathName,FileName];

            if(ischar(filePath))
                openMissionPlanFile(app, filePath, write_to_output_func, application_title, celBodyData, FileName, handles);
            end

            set(handles.newMissionPlanToolbar,'Enable','on');
            set(handles.openMissionPlanToolbar,'Enable','on');
            set(handles.saveMissionPlanToolbar,'Enable','on');
        end

        % Callback function: openMissionPlanToolbar
        function openMissionPlanToolbar_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to openMissionPlanToolbar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(hObject,'Enable','off');
            drawnow nocallbacks;
            %                 openMissionPlanMenu_Callback(handles.openMissionPlanMenu, [], handles);
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            openMissionPlanMenu_Callback(app, evt);

            set(handles.newMissionPlanToolbar,'Enable','on');
            set(handles.openMissionPlanToolbar,'Enable','on');
            set(handles.saveMissionPlanToolbar,'Enable','on');
        end

        % Menu selected function: optimizeMissionMenu
        function optimizeMissionMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to optimizeMissionMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');

            addUndoState(app, handles,'Optimize Mission');

            lvdData.optimizer.optimize(writeOutput, true, app.ma_LvdMainGUI);

            runScript(app, handles, lvdData, 1);
            app.processData(handles);
        end

        % Menu selected function: perturbOptVarsMenu
        function perturbOptVarsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to perturbOptVarsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');

            input_str = sprintf(['Enter the percentage amount to perturb the current solution by:\n',...
                '(Minimum = 0%%, Maximum = 100%%)\n',...
                '(Values greater than 5%%-10%% will likely cause the solution to become hard to recover with the optimizer.)']);
            str = inputdlg(input_str, 'Perturb Optimization Variables', [1 75], {num2str(0.01,'%4.2f')});
            if(isempty(str))
                return;
            end

            str = str{1};

            if(checkStrIsNumeric(str) && str2double(str) >= 0 && str2double(str) <= 100)
                addUndoState(app, handles,'Perturb Optim Vars');

                writeOutput(sprintf('Perturbing optimization variables by %s%%.', str),'append');
                pPct = str2double(str);

                lvdData.optimizer.vars.perturbVarsAndUpdate(pPct);

                runScript(app, handles, lvdData, 1);
                app.processData(handles);
            else
                writeOutput(sprintf('Could not perturb optimization variables.  "%s" is an invalid entry.', str),'append');
                beep;
            end
        end

        % Menu selected function: popOutOrbitDisplayMenu
        function popOutOrbitDisplayMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to popOutOrbitDisplayMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            hFig = figure('Visible','off');
            hAx = axes(hFig);
            inlineDispAxes = handles.dispAxes;
            handles.dispAxes = hAx;
            set(hAx,'UserData',get(handles.dispAxes,'UserData'));

            hFig.DeleteFcn = @(src,evt) popOutOrbitDispDelFcn(app, src,evt, inlineDispAxes, handles);

            wb = uiprogressdlg(app.ma_LvdMainGUI, "Message",'Popping out orbit display...', "Icon","info", "Indeterminate",true);
            %             wb = waitbar(0,'Popping out orbit display...');
            %             wbch = allchild(wb);
            %             jp = wbch(1).JavaPeer;
            %             jp.setIndeterminate(1);

            try
                app.processData(handles);
            catch ME %#ok<NASGU>
                %nothing
            end

            title(hAx, handles.dispAxisTitleLabel.String);

            close(wb);
            hFig.Visible = 'on';

            cameratoolbar(hFig, 'Show');
        end

        % Menu selected function: redoMenu
        function redoMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to redoMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            undoRedo = getappdata(handles.ma_LvdMainGUI,'undoRedo');
            writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');

            [~, undoActionName] = undoRedo.shouldRedoMenuBeEnabled();

            writeOutput(sprintf('Redoing %s...',undoActionName),'append');
            lvdData = undoRedo.redo();

            if(not(isempty(lvdData)))
                setappdata(handles.ma_LvdMainGUI,'lvdData',lvdData);

                addlistener(lvdData.script, 'EventPropagationStarted', @(src,evt) app.handleEvtPropagationStarted(src, evt));
                addlistener(lvdData.script, 'ScriptPropagationStarted', @(src,evt) app.handleScriptPropagationStarted(src, evt));
                addlistener(lvdData.script, 'ScriptPropagationFinished', @(src,evt) app.handleScriptPropagationEnded(src, evt));

                runScript(app, handles, lvdData, 1);
                app.processData(handles);
            end

            curName = get(handles.ma_LvdMainGUI,'Name');
            if(~strcmpi(curName(end),'*'))
                set(handles.ma_LvdMainGUI,'Name',[curName,'*']);
            end

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            editMenu_Callback(app, evt);
        end

        % Menu selected function: runScriptMenu
        function runScriptMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to runScriptMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            if(not(isdeployed))
                % profile off; profile('on','-detail','builtin', '-remove_overhead','on'); 
            end

            app.propagateScript(handles, lvdData, 1);
            app.processData(handles);

            if(not(isdeployed))
                % profile viewer;
            end

            
        end

        % Menu selected function: saveAsMissionPlanMenu
        function saveAsMissionPlanMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAsMissionPlanMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            saveMissionAs(app, handles);

            set(handles.newMissionPlanToolbar,'Enable','on');
            set(handles.openMissionPlanToolbar,'Enable','on');
            set(handles.saveMissionPlanToolbar,'Enable','on');
        end

        % Menu selected function: saveMissionPlanMenu
        function saveMissionPlanMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveMissionPlanMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            current_save_location = getappdata(handles.ma_LvdMainGUI,'current_save_location');
            if(isempty(current_save_location))
                saveMissionAs(app, handles);
            else
                saveMission(app, handles,current_save_location);
            end

            set(handles.newMissionPlanToolbar,'Enable','on');
            set(handles.openMissionPlanToolbar,'Enable','on');
            set(handles.saveMissionPlanToolbar,'Enable','on');
        end

        % Callback function: saveMissionPlanToolbar
        function saveMissionPlanToolbar_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveMissionPlanToolbar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(hObject,'Enable','off');
            drawnow nocallbacks;
            %                 saveMissionPlanMenu_Callback(handles.saveMissionPlanMenu, [], handles);
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            saveMissionPlanMenu_Callback(app, evt);

            set(handles.newMissionPlanToolbar,'Enable','on');
            set(handles.openMissionPlanToolbar,'Enable','on');
            set(handles.saveMissionPlanToolbar,'Enable','on');
        end

        % Value changed function: scriptListbox
        function scriptListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to scriptListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns scriptListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from scriptListbox
            %             if(strcmpi(get(handles.ma_LvdMainGUI,'SelectionType'),'open'))
            %                 addUndoState(app, handles,'Edit Event');
            %
            %                 eventNum = get(hObject,'Value');
            %                 lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            %
            %                 event = lvdData.script.getEventForInd(eventNum);
            %                 lvd_editEventGUI(event, false);
            %
            %                 runScript(app, handles, lvdData, 1);
            %                 app.processData(handles);
            %             end

            % zoom(app.ma_LvdMainGUI, 'off');
            % rotate3d(app.ma_LvdMainGUI, 'off');
            % pan(app.ma_LvdMainGUI, 'off');
        end

        % Menu selected function: selectOptimizationAlgosMenu
        function selectOptimizationAlgosMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to selectOptimizationAlgosMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');

            addUndoState(app, handles,'Edit Optimizer Algorithm');

            lvd_OptimizerSelectionGUI_App(lvdData);

            if(lvdData.optimizer.getSelectedOptimizer().usesParallel())
                numWorkers = lvdData.optimizer.getSelectedOptimizer().getNumParaWorkers();
                startParallelPool(app.ma_LvdMainGUI, writeOutput, numWorkers);
            end
        end

        % Menu selected function: settingsMenu
        function settingsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to settingsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            isAutoProp = lvdData.settings.autoPropScript;
            if(isAutoProp)
                set(handles.autopropagateMenu, 'Checked', 'on');
            else
                set(handles.autopropagateMenu, 'Checked', 'off');
            end

            disEvtPropTimes = lvdData.settings.disEvtPropTimes;
            if(disEvtPropTimes)
                set(handles.displayEvtPropTimesInLogFileMenu, 'Checked', 'on');
            else
                set(handles.displayEvtPropTimesInLogFileMenu, 'Checked', 'off');
            end
        end

        % Menu selected function: showConstrJacobianHeatMapMenu
        function showConstrJacobianHeatMapMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to showConstrJacobianHeatMapMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            constrLbls = lvdData.optimizer.constraints.getListboxStr();
            [x0All, ~, varLbls] = lvdData.optimizer.vars.getTotalScaledXVector();

            if(~isempty(constrLbls) && ~isempty(varLbls))
                %                 hHlpDlg = helpdlg('Computing constraint Jacobian with current trajectory.  Please wait...','Computing Jacobian');
                hHlpDlg = uiprogressdlg(app.ma_LvdMainGUI, 'Message','Computing constraint Jacobian with current trajectory.  Please wait...', ...
                    'Title', 'Launch Vehicle Designer', ...
                    'Indeterminate','on');

                try
                    evtToStartScriptExecAt = lvdData.script.getEventForInd(1);
                    nonlcon = @(x) lvdData.optimizer.constraints.evalConstraints(x, true, evtToStartScriptExecAt, false);
                    jacNonlcon = @(x) computeJacNonlconFunc(app, x, nonlcon);

                    cAtX0 = jacNonlcon(x0All);

                    if(not(isempty(gcp('nocreate'))))
                        useParallel = true;
                    else
                        useParallel = false;
                    end

                    finiteDiffMeth = CustomFiniteDiffsCalculationMethod();
                    jac = abs(finiteDiffMeth.computeJacobian(jacNonlcon, x0All, cAtX0, useParallel));

                    zeroColInds = find(all(jac < eps));
                    totalColInds = 1:1:size(jac,2);
                    nonZeroColInds = setdiff(totalColInds,zeroColInds);

                    filteredJac = jac(:,nonZeroColInds);
                    filteredVarLbls = varLbls(nonZeroColInds);

                    [c, ceq, ~, ~, ~, ~, ~, ~, ~, ~] = nonlcon(x0All);
                    heatMapConstrLbls = {};
                    totNumIneqConstr = length(c)/2;
                    totNumConstr = totNumIneqConstr + length(ceq);
                    for(i=1:totNumIneqConstr)
                        heatMapConstrLbls{end+1} = sprintf('%s (Lwr Bnd)', constrLbls{i}); %#ok<AGROW>
                        heatMapConstrLbls{end+1} = sprintf('%s (Upr Bnd)', constrLbls{i}); %#ok<AGROW>
                    end

                    heatMapConstrLbls = horzcat(heatMapConstrLbls, constrLbls(totNumIneqConstr+1:totNumConstr));

                    hFig = figure();
                    hHeatMap = heatmap(hFig, filteredVarLbls,heatMapConstrLbls,filteredJac);

                    hHeatMap.Title = {'Increase the scale factor of','any constraint with values >> 1'};
                    hHeatMap.XLabel = 'Variables';
                    hHeatMap.YLabel = 'Constraints';
                catch ME
                    errordlg(sprintf('The following error was encountered while trying to compute the Jacobian:\n\n %s',ME.message));
                end

                if(ishandle(hHlpDlg) && isvalid(hHlpDlg))
                    close(hHlpDlg);
                end
            end
        end

        % Menu selected function: sparseIntegratorOutputMenu
        function sparseIntegratorOutputMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to sparseIntegratorOutputMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');

            addUndoState(app, handles,'Toggle Sparse Output');

            if strcmp(get(gcbo, 'Checked'),'on')
                set(gcbo, 'Checked', 'off');
                lvdData.settings.isSparseOutput = false;
                writeOutput('Sparse output mode disabled.  Full integrator output will be provided and plotted.','append');
            else
                set(gcbo, 'Checked', 'on');
                lvdData.settings.isSparseOutput = true;
                writeOutput('Sparse output mode enabled.  Only first and last states from each event will be provided and plotted.','append');
            end

            runScript(app, handles, lvdData, 1);
            app.processData(handles);
        end

        % Callback function
        function toggleCameraToolbar_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to toggleCameraToolbar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %                 cameratoolbar(handles.ma_LvdMainGUI, 'Toggle');
            lvd_AdjustCameraGUI_App(app.dispAxes);
        end

        % Menu selected function: toggleOptimForSelEventMenu
        function toggleOptimForSelEventMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            %             hObject = handles.toggleOptimForSelEventMenu;

            % hObject    handle to toggleOptimForSelEventMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            if(not(isempty(app.scriptListbox.Value)))
                eventNum = app.scriptListbox.Value.getEventNum();
                event = lvdData.script.getEventForInd(eventNum);

                if(not(isempty(event)))
                    addUndoState(app, handles,sprintf('Toggle Optimization on Event %u', eventNum));
                    event.toggleOptimDisable(lvdData);

                    app.processData(handles);
                end
            end
        end

        % Menu selected function: undoMenu
        function undoMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            %             hObject = handles.undoMenu;

            % hObject    handle to undoMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            undoRedo = getappdata(handles.ma_LvdMainGUI,'undoRedo');
            writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');

            [~, undoActionName] = undoRedo.shouldUndoMenuBeEnabled();

            writeOutput(sprintf('Undoing %s...',undoActionName),'append');
            lvdData = undoRedo.undo(lvdData);

            setappdata(handles.ma_LvdMainGUI,'lvdData',lvdData);

            addlistener(lvdData.script, 'EventPropagationStarted', @(src,evt) app.handleEvtPropagationStarted(src, evt));
            addlistener(lvdData.script, 'ScriptPropagationStarted', @(src,evt) app.handleScriptPropagationStarted(src, evt));
            addlistener(lvdData.script, 'ScriptPropagationFinished', @(src,evt) app.handleScriptPropagationEnded(src, evt));

            runScript(app, handles, lvdData, 1);
            app.processData(handles);

            curName = get(handles.ma_LvdMainGUI,'Name');
            if(~strcmpi(curName(end),'*'))
                set(handles.ma_LvdMainGUI,'Name',[curName,'*']);
            end

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            editMenu_Callback(app, evt);
        end

        % Menu selected function: uploadImpDvActionToKspMenu
        function uploadImpDvActionToKspMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to uploadImpDvActionToKspMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            if(not(isempty(app.scriptListbox.Value)))
                eventNum = app.scriptListbox.Value.getEventNum();
                event = lvdData.script.getEventForInd(eventNum);
                actions = event.actions;

                bool = arrayfun(@(x) isa(x,'AddDeltaVAction'), actions);
                if(any(bool)) %there's at least one delta-v action
                    if(sum(bool) == 1) %there's one delta-v action
                        deltaVAction = actions(bool);
                    else               %there's more than one delta-v action
                        actionsListBoxStr = event.getActionsListboxStr();
                        actionsListBoxStr = actionsListBoxStr(bool);
                        dvActions = actions(bool);

                        out = AppDesignerGUIOutput();
                        listdlgARH_App('ListString',actionsListBoxStr, ...
                            'SelectionMode', 'single', ...
                            'ListSize', [300 300], ...
                            'Name', 'Select Delta-V Action', ...
                            'PromptString', 'Select Delta-V Action to Upload:', ...
                            'out',out);
                        Selection = out.output{1};
                        ok = out.output{2};
                        if(ok)
                            deltaVAction = dvActions(Selection);
                        else
                            return;
                        end
                    end

                else
                    errordlg('Error: Selected event does not have an impulsive delta-v action.','Upload Failed!');
                    return;
                end

                subStateLog = lvdData.stateLog.getAllStateLogEntriesForEvent(event);
                if(event.execActionsNode == ActionExecNodeEnum.AfterProp)
                    stateLogEntry = subStateLog(end-1);
                elseif(event.execActionsNode == ActionExecNodeEnum.BeforeProp)
                    stateLogEntry = subStateLog(1);
                end

                data = deltaVAction.getUploadDvToKspData(stateLogEntry);
                uploadManeuverToKSP_App(data);
            end
        end

        % Menu selected function: viewMenu
        function viewMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to viewMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            viewProfiles = lvdData.viewSettings.getProfilesArray();

            oldMenus = handles.setActiveViewProfileMenu.Children;
            for(i=1:length(oldMenus))
                delete(oldMenus(i));
            end

            for(i=1:length(viewProfiles))
                profile = viewProfiles(i);

                if(lvdData.viewSettings.isProfileActive(profile))
                    checkedTxt = 'on';
                else
                    checkedTxt = 'off';
                end

                cbFh = @(src,evt) viewProfileMenuSelectedCallback(app, src,evt, profile, lvdData.viewSettings, handles);

                menus(i) = uimenu(handles.setActiveViewProfileMenu, 'Text',profile.name, ...
                    'Checked',checkedTxt, ...
                    'MenuSelectedFcn',cbFh); %#ok<AGROW,NASGU>
            end
        end

        % Menu selected function: viewStateAfterSelectedEventMenu
        function viewStateAfterSelectedEventMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to viewStateAfterSelectedEventMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            if(not(isempty(app.scriptListbox.Value)))
                eventNum = app.scriptListbox.Value.getEventNum();
                stateLog = lvdData.stateLog.getMAFormattedStateLogMatrix(true);

                state = stateLog(stateLog(:,13)==eventNum,:);
                state = state(end,:);

                %     propNames = {'Liquid Fuel/Ox','Monopropellant','Xenon'};
                propNames = lvdData.launchVehicle.tankTypes.getFirstThreeTypesCellArr();
                viewSpacecraftStatePopupGUI(propNames, state, eventNum, lvdData.celBodyData);
            end
        end

        % Callback function
        function warnAlertsSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to warnAlertsSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            updateWarnErrorLabels(app, handles, false);
        end

        % Callback function: zoomInPushMenuToggle
        function uitoggletool4_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.zoomInPushMenuToggle.State;
%             zoomModeObject = zoom(app.dispAxes);
%             if state
%                 zoomModeObject.Direction = 'in';
%                 zoomModeObject.Enable = 'on';
%             else
%                 zoomModeObject.Enable = 'off';
%             end
            
            app.resetCameraToolbarManagerCurrentObj();
            cameratoolbar(app.ma_LvdMainGUI, 'SetCoordSys','none');
            if(state)
                cameratoolbar('SetMode','dollyfb');
            else
                cameratoolbar('SetMode','nomode');
            end
        end

        % Callback function: zoomOutPushMenuToggle
        function uitoggletool3_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.zoomOutPushMenuToggle.State;
%             zoomModeObject = zoom(app.dispAxes);
%             if state
%                 zoomModeObject.Direction = 'out';
%                 zoomModeObject.Enable = 'on';
%             else
%                 zoomModeObject.Enable = 'off';
%             end

            app.resetCameraToolbarManagerCurrentObj();
            cameratoolbar(app.ma_LvdMainGUI, 'SetCoordSys','none');
            if(state)
                cameratoolbar('SetMode','zoom');
            else
                cameratoolbar('SetMode','nomode');
            end
        end

        % Callback function: rotatePushMenuToggle
        function uitoggletool2_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable pan based on the
            % tool's current state.
            state = app.rotatePushMenuToggle.State;
%             rotate3d(app.dispAxes, char(state));

            app.resetCameraToolbarManagerCurrentObj();
            cameratoolbar(app.ma_LvdMainGUI, 'SetCoordSys','z');
            if(state)
                cameratoolbar('SetMode','orbit');
            else
                cameratoolbar('SetMode','nomode');
            end
        end

        % Callback function: panPushMenuToggle
        function uitoggletool1_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable rotation based on the
            % tool's current state.
            state = app.panPushMenuToggle.State;
%             pan(app.dispAxes, char(state));

            app.resetCameraToolbarManagerCurrentObj();
            cameratoolbar(app.ma_LvdMainGUI, 'SetCoordSys','none');
            if(state)
                cameratoolbar(app.ma_LvdMainGUI, 'SetMode','pan');
            else
                cameratoolbar(app.ma_LvdMainGUI, 'SetMode','nomode');
            end
        end

        % Callback function
        function DispAxesTimeSliderValueChanging(app, event)

        end

        % Callback function
        function uitoggletool1On(app, event)

        end

        % Window button down function: ma_LvdMainGUI
        function ma_LvdMainGUIWindowButtonDown(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            selType = app.ma_LvdMainGUI.SelectionType;
            % curObj = app.ma_LvdMainGUI.CurrentObject;

            if(strcmpi(selType, 'normal'))
                if(not(isMATLABReleaseOlderThan("R2022b")))
                    zoom(app.ma_LvdMainGUI, 'off');
                    rotate3d(app.ma_LvdMainGUI, 'off');
                    pan(app.ma_LvdMainGUI, 'off');

                    app.gridLayout7Resizer.windowButtonDownCallback();
                    app.gridLayout4Resizer.windowButtonDownCallback();
                    app.gridLayout3Resizer.windowButtonDownCallback();
                    app.gridLayout23Resizer.windowButtonDownCallback();
                end
            end

            % if(strcmpi(selType, 'open'))
            %     % if(curObj == app.scriptListbox)
            %     %     % addUndoState(app, handles,'Edit Event');
            %     %     % 
            %     %     % event = curObj.Value;
            %     %     % output = AppDesignerGUIOutput({false});
            %     %     % lvd_editEventGUI_App(event, false, output);
            %     %     % 
            %     %     % runScript(app, handles, lvdData, 1);
            %     %     % app.processData(handles);
            %     % 
            %     % elseif(curObj == app.nonSeqEventsListbox)
            %     %     % addUndoState(app, handles,'Edit Non-Sequential Event');
            %     %     % 
            %     %     % nonSeqEvt = curObj.Value;
            %     %     % lvd_editNonSeqEventGUI_App(nonSeqEvt, lvdData);
            %     %     % 
            %     %     % runScript(app, handles, lvdData, 1);
            %     %     % app.processData(handles);
            %     % end
            % else
            %     if(not(isMATLABReleaseOlderThan("R2022b")))
            %         app.gridLayout7Resizer.windowButtonDownCallback();
            %         app.gridLayout4Resizer.windowButtonDownCallback();
            %         app.gridLayout3Resizer.windowButtonDownCallback();
            %         app.gridLayout23Resizer.windowButtonDownCallback();
            %     end
            % end
        end

        % Menu selected function: EditSensorsMenu
        function EditSensorsMenuSelected(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            addUndoState(app, handles,'Edit Sensors');
            lvd_EditSensorsGUI_App(lvdData);

            runScript(app, handles, lvdData, 1);
            app.processData(handles);
        end

        % Menu selected function: EditSensorTargetsMenu
        function EditSensorTargetsMenuSelected(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');

            addUndoState(app, handles,'Edit Sensor Targets');
            lvd_EditSensorTargetsGUI_App(lvdData);

            runScript(app, handles, lvdData, 1);
            app.processData(handles);
        end

        % Menu selected function: SensorReportsMenu
        function SensorReportsMenuSelected(app, event)
            lvdData = getappdata(app.ma_LvdMainGUI,'lvdData');

            [~,sensors] = lvdData.sensors.getListboxStr();
            [~,targets] = lvdData.sensorTgts.getListboxStr();

            if(numel(sensors)>0 && numel(targets)>0)
                lvd_SensorReports(lvdData);
            else
                uialert(app.ma_LvdMainGUI,'There must be at least one sensor and one sensor target in the scenario to run a Sensor Report.', 'Cannot Run Sensor Report', "Icon","error");
            end
        end

        % Key press function: ma_LvdMainGUI
        function ma_LvdMainGUIKeyPress(app, event)
            %             key = event.Key;
        end

        % Menu selected function: NewMissionPlanfromMFMSOutputMenu
        function NewMissionPlanfromMFMSOutputMenuSelected(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if(isMissionPlanSaved(app, handles))
                askToClear = false;
            else
                askToClear = true;
            end

            if(askToClear)
                %                 response = questdlg(['All unsaved work will be lost.  Continue?'],'Create new mission plan?','Yes','No','No');
                response = uiconfirm(app.ma_LvdMainGUI,'All unsaved work will be lost.  Continue?', ...
                    'Launch Vehicle Designer', ...
                    'Options',{'Yes','No'}, ...
                    'DefaultOption','Yes');
            else
                response = 'Yes';
            end

            if(~strcmpi(response,'Yes'))
                return;
            end

            [file,path] = uigetfile(".mat", "Open MFMS Output Data", "mfmsData.mat", "MultiSelect","off");
            if(file ~= 0)
                filename = fullfile(path, file);
                load(filename, "mfmsOutputs");

                if(~exist("mfmsOutputs","var"))
                    uialert(app.ma_LvdMainGUI, "The loaded MAT file was either invalid or corrupt.  Please try again.", "Loading Error", "Icon","error", "Modal",true);
                    return;
                end
            else
                return;
            end

            setappdata(handles.ma_LvdMainGUI,'undoRedo',LVD_UndoRedoStateSet());

            celBodyData = mfmsOutputs.celBodyData;
            setappdata(handles.ma_LvdMainGUI,'celBodyData', celBodyData);

            write_to_output_func = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
            application_title = getappdata(handles.ma_LvdMainGUI,'application_title');
            if(askToClear)
                write_to_output_func('Creating new mission plan... ','append');
            end

            lvdData = LvdData.getLvdDataFromMfmsOutputs(mfmsOutputs);
            setappdata(handles.ma_LvdMainGUI,'lvdData',lvdData);
            setappdata(handles.ma_LvdMainGUI,'current_save_location','');

            setDeleteButtonEnable(app, lvdData, handles);
            setNonSeqDeleteButtonEnable(app, lvdData, handles);

            setappdata(app.DispAxesTimeSlider,'lastTime',NaN);
            timeSliderCb = @(src,evt) timeSliderStateChanged(src,evt, lvdData, handles, app);
            app.DispAxesTimeSlider.ValueChangingFcn = timeSliderCb;

            enableDisableArrowButtons(app, lvdData, handles);

            addlistener(lvdData.script, 'EventPropagationStarted', @(src,evt) app.handleEvtPropagationStarted(src, evt));
            addlistener(lvdData.script, 'ScriptPropagationStarted', @(src,evt) app.handleScriptPropagationStarted(src, evt));
            addlistener(lvdData.script, 'ScriptPropagationFinished', @(src,evt) app.handleScriptPropagationEnded(src, evt));

            msg = "The mission has been generated from MFMS output.  Please consider doing the following next:" + newline +...
                "" + newline + ...
                "1) Scale your newly created continuity constraints." + newline + ...
                "2) Verify that bounds on optimization variables make sense." + newline + ...
                "3) Scale your objective function." + newline + ...
                "4) Select an optimizer and update its settings, if needed." + newline + ...
                "5) Run the optimizer to satisfy the constraints and create a valid end to end mission.";
            uialert(app.ma_LvdMainGUI, msg, "Mission Created", "Modal",true, "Icon","success");

            app.propagateScript(handles, lvdData, 1)
            app.processData(handles);

            set(handles.ma_LvdMainGUI,'Name', application_title);

            if(askToClear)
                write_to_output_func(['Done.'],'appendSameLine'); %#ok<*NBRAK>
            end

            set(handles.newMissionPlanToolbar,'Enable','on');
            set(handles.openMissionPlanToolbar,'Enable','on');
            set(handles.saveMissionPlanToolbar,'Enable','on');
        end

        % Menu selected function: 
        % ConvertImpulsiveDVActionintoFiniteBurnMenu
        function ConvertImpulsiveDVActionintoFiniteBurnMenuSelected(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            lvdData = getappdata(app.ma_LvdMainGUI,'lvdData');

            event = app.scriptListbox.Value;
            if(not(isempty(event)))
                actions = event.actions;
                bool = arrayfun(@(x) isa(x,'AddDeltaVAction'), actions);

                if(any(bool))
                    if(sum(bool) == 1) %there's one delta-v action
                        deltaVAction = actions(bool);
                    else               %there's more than one delta-v action
                        actionsListBoxStr = event.getActionsListboxStr();
                        actionsListBoxStr = actionsListBoxStr(bool);
                        dvActions = actions(bool);

                        out = AppDesignerGUIOutput();
                        listdlgARH_App('ListString',actionsListBoxStr, ...
                            'SelectionMode', 'single', ...
                            'ListSize', [300 300], ...
                            'Name', 'Select Delta-V Action', ...
                            'PromptString', 'Select Delta-V Action to Develop:', ...
                            'out',out);
                        Selection = out.output{1};
                        ok = out.output{2};
                        if(ok)
                            deltaVAction = dvActions(Selection);
                        else
                            return;
                        end
                    end

                    addUndoState(app, handles,'Convert Impulsive Delta-V');

                    app.convertDeltaVAction(deltaVAction);

                    app.propagateScript(handles, lvdData, 1);
                    app.processData(handles);
                else
                    uialert(app.ma_LvdMainGUI, 'There must be at least one Add Delta-V Action on this event to develop.', 'Cannot Develop Delta-V', "Icon","error");
                end
            end
        end

        % Context menu opening function: scriptListboxContextMenu
        function scriptListboxContextMenuOpening(app, event)
            event = app.scriptListbox.Value;
            if(not(isempty(event)))
                actions = event.actions;
                bool = arrayfun(@(x) isa(x,'AddDeltaVAction'), actions);

                if(any(bool))
                    app.ConvertImpulsiveDVActionintoFiniteBurnMenu.Enable = true;
                    app.uploadImpDvActionToKspMenu.Enable = true;
                else
                    app.ConvertImpulsiveDVActionintoFiniteBurnMenu.Enable = false;
                    app.uploadImpDvActionToKspMenu.Enable = false;
                end
            else
                app.ConvertImpulsiveDVActionintoFiniteBurnMenu.Enable = false;
                app.uploadImpDvActionToKspMenu.Enable = false;
            end
        end

        % Menu selected function: EditEventMenu
        function EditEventMenuSelected(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            lvdData = getappdata(app.ma_LvdMainGUI,'lvdData');

            event = app.scriptListbox.Value;
            if(not(isempty(event)))
                addUndoState(app, handles,'Edit Event');

                output = AppDesignerGUIOutput({false});
                lvd_editEventGUI_App(event, false, output);

                runScript(app, handles, lvdData, 1);
                app.processData(handles);
            end
        end

        % Callback function
        function CameraControlsMenuSelected(app, event)
            lvd_AdjustCameraGUI_App(app.dispAxes);
        end

        % Callback function
        function OpenCameraControlsPushToolClicked(app, event)
            lvd_AdjustCameraGUI_App(app.dispAxes);
        end

        % Window button motion function: ma_LvdMainGUI
        function ma_LvdMainGUIWindowButtonMotion(app, event)
            % if(app.warning1Lbl.Visible == true && app.isMouseOverWarnErrLbl(app.warning1Lbl))
            %     mouseIsOverLbl = true;
            %     textStr = getappdata(app.warning1Lbl,'tooltipstring');
            % 
            % elseif(app.warning2Lbl.Visible == true && app.isMouseOverWarnErrLbl(app.warning2Lbl))
            %     mouseIsOverLbl = true;
            %     textStr = getappdata(app.warning2Lbl,'tooltipstring');
            % 
            % elseif(app.warning3Lbl.Visible == true && app.isMouseOverWarnErrLbl(app.warning3Lbl))
            %     mouseIsOverLbl = true;
            %     textStr = getappdata(app.warning3Lbl,'tooltipstring');
            % 
            % elseif(app.warning4Lbl.Visible == true && app.isMouseOverWarnErrLbl(app.warning4Lbl))
            %     mouseIsOverLbl = true;
            %     textStr = getappdata(app.warning4Lbl,'tooltipstring');
            % 
            % elseif(app.warning5Lbl.Visible == true && app.isMouseOverWarnErrLbl(app.warning5Lbl))
            %     mouseIsOverLbl = true;
            %     textStr = getappdata(app.warning5Lbl,'tooltipstring');
            % 
            % elseif(app.warning6Lbl.Visible == true && app.isMouseOverWarnErrLbl(app.warning6Lbl))
            %     mouseIsOverLbl = true;
            %     textStr = getappdata(app.warning6Lbl,'tooltipstring');
            % 
            % else
            %     mouseIsOverLbl = false;
            %     try
            %         app.warnErrTooltipPanel.Visible = 'off';
            %     catch
            %         %nothing
            %     end
            % end
            % 
            % if(mouseIsOverLbl)
            %     t = text(app.warnErrInvisibleAxes, 0,0, textStr, 'Units','pixels', 'FontName','Helvetica', 'FontUnits','pixels', 'FontSize',12, 'FontWeight','normal', "VerticalAlignment","bottom");
            % 
            %     tPos = t.Extent + 2;
            %     mousePt = app.ma_LvdMainGUI.CurrentPoint;
            % 
            %     figPos = app.ma_LvdMainGUI.Position;
            %     xPos = mousePt(1) - tPos(3);
            %     if(xPos < 0 && mousePt(1) + tPos(3) < figPos(3))
            %         xPos = mousePt(1);
            %     elseif(xPos < 0)
            %         xPos = 0;
            %     end
            % 
            %     pos = [xPos, mousePt(2), tPos(3:4)];
            % 
            %     app.warnErrTooltipPanel.Visible = 'on';
            %     app.warnErrTooltipPanel.Position = pos;
            %     app.warnErrTooltipLabel.Text = textStr;
            % 
            %     delete(t);
            % end

            if(not(isMATLABReleaseOlderThan("R2022b")))
                app.gridLayout23Resizer.windowButtonMotionCallback();
                app.gridLayout3Resizer.windowButtonMotionCallback();
                app.gridLayout7Resizer.windowButtonMotionCallback();
                app.gridLayout4Resizer.windowButtonMotionCallback();                
            end
        end

        % Menu selected function: PluginVariablesMenu
        function PluginVariablesMenuSelected(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            addUndoState(app, handles,'Edit Plugin Variables');       
            
            lvdData = getappdata(app.ma_LvdMainGUI,'lvdData');
            lvd_EditPluginVariables_App(lvdData);

            app.runScript(handles, lvdData, 1);
            app.processData(handles);
        end

        % Menu selected function: RunCaseMatrixMenu
        function RunCaseMatrixMenuSelected(app, event)
            lvdData = getappdata(app.ma_LvdMainGUI,'lvdData');
            lvd_runCaseMatrix_App(lvdData);
        end

        % Menu selected function: ScaleConstraintsCurrentValueMenu
        function ScaleConstraintsCurrentValueMenuSelected(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            lvdData = getappdata(app.ma_LvdMainGUI,'lvdData');

            consts = lvdData.optimizer.constraints.consts;
            active = [lvdData.optimizer.constraints.consts.active];

            if(~isempty(consts) || any(active))
                app.propagateScript(handles, lvdData, 1);
    
                lastConstrRun = lvdData.optimizer.constraints.lastRunValues;
                values = lastConstrRun.value;
    
                if(not(isempty(values)))
                    scaleFactors = 10.^ceil(log10(abs(values)));
                    scaleFactors(scaleFactors < 1) = 1;
        
                    addUndoState(app, handles,'Auto-scale Constraints by Value');  
        
                    j=1;
                    str = string.empty(1,0);
                    for(i=1:length(consts))
                        const = consts(i);
        
                        if(const.active)
                            str(end+1) = sprintf("%s (New Scale Factor: %0.1f, Old Scale Factor: %0.1f)", const.getName, scaleFactors(j), const.normFact); %#ok<AGROW> 

                            const.normFact = scaleFactors(j);
                            j=j+1;
                        end
                    end
                    strCat = strjoin(str,'\n');

                    uialert(app.ma_LvdMainGUI, sprintf('Constraints scaled successfully.  Results:\n\n%s', strCat), 'Scale Constraints by Value', "Icon","success");
                end
            else
                uialert(app.ma_LvdMainGUI, 'There are no constraints in the scenario.', 'Error', "Icon",'error');
            end
        end

        % Menu selected function: ScaleConstraintsJacobianMenu
        function ScaleConstraintsJacobianMenuSelected(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            lvdData = getappdata(app.ma_LvdMainGUI,'lvdData');

            consts = lvdData.optimizer.constraints.consts;
            active = [lvdData.optimizer.constraints.consts.active];

            hPrgDlg = [];

            if(~isempty(consts) || any(active))
                app.propagateScript(handles, lvdData, 1);
    
                try
                    [x0All, ~, ~] = lvdData.optimizer.vars.getTotalScaledXVector();
    
                    evtToStartScriptExecAt = lvdData.script.getEventForInd(1);
                    nonlcon = @(x) lvdData.optimizer.constraints.evalConstraints(x, true, evtToStartScriptExecAt, false);
                    jacNonlcon = @(x) computeJacNonlconFunc(app, x, nonlcon);
    
                    cAtX0 = jacNonlcon(x0All);
    
                    if(not(isempty(gcp('nocreate'))))
                        useParallel = true;
                    else
                        useParallel = false;
                    end
    
                    hPrgDlg = uiprogressdlg(app.ma_LvdMainGUI, "Indeterminate","on", "Message",'Computing Jacobian, please wait.', 'Icon','info', 'Title','Launch Vehicle Designer');

                    finiteDiffMeth = CustomFiniteDiffsCalculationMethod();
                    jac = abs(finiteDiffMeth.computeJacobian(jacNonlcon, x0All, cAtX0, useParallel));
    
                    close(hPrgDlg);

                    rawScaleFactors = max(abs(jac),[],2);
                    scaleFactors = 10.^ceil(log10(abs(rawScaleFactors)));
                    scaleFactors(scaleFactors < 1) = 1;
    
                    addUndoState(app, handles,'Auto-scale Constraints by Jacobian Values');  

                    j=1;
                    str = string.empty(1,0);
                    for(i=1:length(consts))
                        const = consts(i);
        
                        if(const.active)
                            str(end+1) = sprintf("%s (New Scale Factor: %0.1f, Old Scale Factor: %0.1f)", const.getName, scaleFactors(j), const.normFact); %#ok<AGROW> 

                            const.normFact = scaleFactors(j);
                            j=j+1;
                        end
                    end
                    strCat = strjoin(str,'\n');

                    uialert(app.ma_LvdMainGUI, sprintf('Constraints scaled successfully.  Results:\n\n%s', strCat), 'Scale Constraints by Jacobian', "Icon","success");
                catch ME
                    if(not(isempty(hPrgDlg)))
                        close(hPrgDlg);
                    end

                    uialert(app.ma_LvdMainGUI, sprintf('There was an error computing the Jacobian.  Message:\n\n%s', ME.message), 'Error', "Icon",'error');
                end
            else
                uialert(app.ma_LvdMainGUI, 'There are no constraints in the scenario.', 'Error', "Icon",'error');
            end
        end

        % Menu selected function: setIntegrationStepSizeForAllEventsMenu
        function setIntegrationStepSizeForAllEventsMenuSelected(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
            
            input_str = sprintf(['Enter the desired integration step size for all events:\n',...
                                 '(Minimum = -1, Maximum = Inf)\n',...
                                 '(This will influence script execution speed.  Set to -1 to use adaptive stepsize output.)']);
            str = inputdlg(input_str, 'Global Integrator Step Size', [1 75], {'1.0'});
            if(isempty(str))
                return;
            end
            
            str = str{1};
            
            if(checkStrIsNumeric(str) && str2double(str) ~= 0 && str2double(str) <= Inf)
                addUndoState(app, handles,'Set Global Integrator Step Size');  
                
                writeOutput(sprintf('Setting integrator step size on all events to %s.', str),'append');
                
                stepSize = str2double(str);
                totNumEvts = lvdData.script.getTotalNumOfEvents();
                for(i=1:totNumEvts)
                    evt = lvdData.script.getEventForInd(i);
        
                    evt.integratorObj.getOptions().setIntegratorStepSize(stepSize);
                end
                
                runScript(app, handles, lvdData, 1);
                app.processData(handles);
%                 lvd_processData(handles);
            else
                writeOutput(sprintf('Could not set the desired integration step size on all events.  "%s" is an invalid entry.', str),'append');
                beep;
            end
        end

        % Callback function: RestoreViewToolbarMenu
        function RestoreViewToolbarMenuClicked(app, event)
            try
                cameratoolbar('ResetCamera');
            catch ME
                warning(ME.message);
            end
        end

        % Menu selected function: LVDConsoleMenu
        function LVDConsoleMenuSelected(app, event)
            lvdData = getappdata(app.ma_LvdMainGUI,'lvdData');
            lvd_Console_App(lvdData);
        end

        % Menu selected function: CopyInitialOrbitToClipboardMenu
        function CopyInitialOrbitToClipboardMenuSelected(app, event)
            copyOrbitToClipboardFromStateLog(app, event, app.InitialSpacecraftStateTextArea);
        end

        % Menu selected function: ChangeInitialReferenceFrameElementSetMenu
        function ChangeInitialReferenceFrameElementSetMenuSelected(app, event)
            lvdData = getappdata(app.ma_LvdMainGUI,'lvdData');

            curElementSet = app.initialOrbitDisplayElementSet;
            curReferenceFrame = app.initialOrbitDisplayRefFrame;
            out = AppDesignerGUIOutput({false, false});

            lvd_EditDisplayFrameAndElementSet_App(lvdData, curElementSet, curReferenceFrame, out);

            if(not(islogical(out.output{1})))
                app.initialOrbitDisplayRefFrame = out.output{1};
                app.initialOrbitDisplayElementSet = out.output{2};
    
                [stateStr, stateTooltipStr, clipboardData] = lvd_UpdateStateReadout(app.initialOrbitDisplayRefFrame, app.initialOrbitDisplayElementSet, 'initial', lvdData.stateLog);
                app.InitialSpacecraftStateTextArea.Text = stateStr;
                app.InitialSpacecraftStateTextArea.Tooltip = stateTooltipStr;
                app.InitialSpacecraftStateTextArea.UserData = clipboardData;
            end
        end

        % Menu selected function: CopyFinalOrbittoClipboardMenu
        function CopyFinalOrbittoClipboardMenuSelected(app, event)
            copyOrbitToClipboardFromStateLog(app, event, app.FinalSpacecraftStateTextArea);
        end

        % Menu selected function: ChangeFinalReferenceFrameElementSetMenu
        function ChangeFinalReferenceFrameElementSetMenuSelected(app, event)
            lvdData = getappdata(app.ma_LvdMainGUI,'lvdData');

            curElementSet = app.finalOrbitDisplayElementSet;
            curReferenceFrame = app.finalOrbitDisplayRefFrame;
            out = AppDesignerGUIOutput({false, false});

            lvd_EditDisplayFrameAndElementSet_App(lvdData, curElementSet, curReferenceFrame, out);

            if(not(islogical(out.output{1})))
                app.finalOrbitDisplayRefFrame = out.output{1};
                app.finalOrbitDisplayElementSet = out.output{2};
    
                [stateStr, stateTooltipStr, clipboardData] = lvd_UpdateStateReadout(app.finalOrbitDisplayRefFrame, app.finalOrbitDisplayElementSet, 'final', lvdData.stateLog);
                app.FinalSpacecraftStateTextArea.Text = stateStr;
                app.FinalSpacecraftStateTextArea.Tooltip = stateTooltipStr;
                app.FinalSpacecraftStateTextArea.UserData = clipboardData;
            end
        end

        % Window button up function: ma_LvdMainGUI
        function ma_LvdMainGUIWindowButtonUp(app, event)
            if(not(isMATLABReleaseOlderThan("R2022b")))
                app.gridLayout7Resizer.windowButtonUpCallback();
                app.gridLayout4Resizer.windowButtonUpCallback();
                app.gridLayout3Resizer.windowButtonUpCallback();
                app.gridLayout23Resizer.windowButtonUpCallback();
            end
        end

        % Menu selected function: setActiveViewProfileMenu
        function setActiveViewProfileMenuSelected(app, event)
            
        end

        % Double-clicked callback: scriptListbox
        function scriptListboxDoubleClicked(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            lvdData = getappdata(app.ma_LvdMainGUI,'lvdData');
            
            addUndoState(app, handles,'Edit Event');
            
            evt = app.scriptListbox.Value;
            if(not(isempty(evt)))
                output = AppDesignerGUIOutput({false});
                lvd_editEventGUI_App(evt, false, output);
    
                app.runScript(handles, lvdData, 1);
                app.processData(handles);
            end
        end

        % Double-clicked callback: nonSeqEventsListbox
        function nonSeqEventsListboxDoubleClicked(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            lvdData = getappdata(app.ma_LvdMainGUI,'lvdData');
            
            addUndoState(app, handles,'Edit Non-Sequential Event');

            nonSeqEvt = app.nonSeqEventsListbox.Value;
            if(not(isempty(nonSeqEvt)))
                lvd_editNonSeqEventGUI_App(nonSeqEvt, lvdData);
                
                app.runScript(handles, lvdData, 1);
                app.processData(handles);
            end
        end

        % Clicked callback: scriptListbox
        function scriptListboxClicked(app, event)
            zoom(app.ma_LvdMainGUI, 'off');
            rotate3d(app.ma_LvdMainGUI, 'off');
            pan(app.ma_LvdMainGUI, 'off');
            drawnow;
        end

        % Clicked callback: nonSeqEventsListbox
        function nonSeqEventsListboxClicked(app, event)
            zoom(app.ma_LvdMainGUI, 'off');
            rotate3d(app.ma_LvdMainGUI, 'off');
            pan(app.ma_LvdMainGUI, 'off');
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ma_LvdMainGUI = uifigure('Visible', 'off');
            app.ma_LvdMainGUI.Name = 'KSPTOT Launch Vehicle Designer';
            app.ma_LvdMainGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.ma_LvdMainGUI.CloseRequestFcn = createCallbackFcn(app, @ma_LvdMainGUI_CloseRequestFcn, true);
            app.ma_LvdMainGUI.WindowButtonDownFcn = createCallbackFcn(app, @ma_LvdMainGUIWindowButtonDown, true);
            app.ma_LvdMainGUI.WindowButtonUpFcn = createCallbackFcn(app, @ma_LvdMainGUIWindowButtonUp, true);
            app.ma_LvdMainGUI.WindowButtonMotionFcn = createCallbackFcn(app, @ma_LvdMainGUIWindowButtonMotion, true);
            app.ma_LvdMainGUI.WindowKeyPressFcn = createCallbackFcn(app, @ma_LvdMainGUI_WindowKeyPressFcn, true);
            app.ma_LvdMainGUI.KeyPressFcn = createCallbackFcn(app, @ma_LvdMainGUIKeyPress, true);
            app.ma_LvdMainGUI.Tag = 'ma_LvdMainGUI';
            app.fileMenu = uimenu(app.ma_LvdMainGUI);
            app.fileMenu.MenuSelectedFcn = createCallbackFcn(app, @fileMenu_Callback, true);
            app.fileMenu.Text = 'File';
            app.fileMenu.Tag = 'fileMenu';
            app.newMissionPlanMenu = uimenu(app.fileMenu);
            app.newMissionPlanMenu.MenuSelectedFcn = createCallbackFcn(app, @newMissionPlanMenu_Callback, true);
            app.newMissionPlanMenu.Tooltip = {'Creates a new LVD mission plan.'};
            app.newMissionPlanMenu.Accelerator = 'N';
            app.newMissionPlanMenu.Text = 'New Mission Plan';
            app.newMissionPlanMenu.Tag = 'newMissionPlanMenu';
            app.openMissionPlanMenu = uimenu(app.fileMenu);
            app.openMissionPlanMenu.MenuSelectedFcn = createCallbackFcn(app, @openMissionPlanMenu_Callback, true);
            app.openMissionPlanMenu.Tooltip = {'Opens an existing LVD mission plan.'};
            app.openMissionPlanMenu.Accelerator = 'L';
            app.openMissionPlanMenu.Text = 'Open Mission Plan';
            app.openMissionPlanMenu.Tag = 'openMissionPlanMenu';
            app.saveMissionPlanMenu = uimenu(app.fileMenu);
            app.saveMissionPlanMenu.MenuSelectedFcn = createCallbackFcn(app, @saveMissionPlanMenu_Callback, true);
            app.saveMissionPlanMenu.Tooltip = {'Saves the currently open LVD mission plan.'};
            app.saveMissionPlanMenu.Accelerator = 'S';
            app.saveMissionPlanMenu.Text = 'Save Mission Plan';
            app.saveMissionPlanMenu.Tag = 'saveMissionPlanMenu';
            app.saveAsMissionPlanMenu = uimenu(app.fileMenu);
            app.saveAsMissionPlanMenu.MenuSelectedFcn = createCallbackFcn(app, @saveAsMissionPlanMenu_Callback, true);
            app.saveAsMissionPlanMenu.Tooltip = {'Saves the currently open LVD mission plan to a new file.'};
            app.saveAsMissionPlanMenu.Text = 'Save Mission Plan As...';
            app.saveAsMissionPlanMenu.Tag = 'saveAsMissionPlanMenu';
            app.NewMissionPlanfromMFMSOutputMenu = uimenu(app.fileMenu);
            app.NewMissionPlanfromMFMSOutputMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMissionPlanfromMFMSOutputMenuSelected, true);
            app.NewMissionPlanfromMFMSOutputMenu.Tooltip = {'Creates an LVD mission plan from a MAT file created in Multi-flyby Maneuver Sequencer.'};
            app.NewMissionPlanfromMFMSOutputMenu.Separator = 'on';
            app.NewMissionPlanfromMFMSOutputMenu.Text = 'New Mission Plan from MFMS Output';
            app.openRecentMissionPlansMenu = uimenu(app.fileMenu);
            app.openRecentMissionPlansMenu.Separator = 'on';
            app.openRecentMissionPlansMenu.Text = 'Open Recent Mission Plans';
            app.openRecentMissionPlansMenu.Tag = 'openRecentMissionPlansMenu';
            app.exitMenu = uimenu(app.fileMenu);
            app.exitMenu.MenuSelectedFcn = createCallbackFcn(app, @exitMenu_Callback, true);
            app.exitMenu.Tooltip = {'Exits Launch Vehicle Designer.'};
            app.exitMenu.Separator = 'on';
            app.exitMenu.Text = 'Exit Launch Vehicle Designer';
            app.exitMenu.Tag = 'exitMenu';
            app.editMenu = uimenu(app.ma_LvdMainGUI);
            app.editMenu.MenuSelectedFcn = createCallbackFcn(app, @editMenu_Callback, true);
            app.editMenu.Text = 'Edit';
            app.editMenu.Tag = 'editMenu';
            app.undoMenu = uimenu(app.editMenu);
            app.undoMenu.MenuSelectedFcn = createCallbackFcn(app, @undoMenu_Callback, true);
            app.undoMenu.Accelerator = 'Z';
            app.undoMenu.Text = 'Undo';
            app.undoMenu.Tag = 'undoMenu';
            app.redoMenu = uimenu(app.editMenu);
            app.redoMenu.MenuSelectedFcn = createCallbackFcn(app, @redoMenu_Callback, true);
            app.redoMenu.Accelerator = 'Y';
            app.redoMenu.Text = 'Redo';
            app.redoMenu.Tag = 'redoMenu';
            app.editMissionNotesMenu = uimenu(app.editMenu);
            app.editMissionNotesMenu.MenuSelectedFcn = createCallbackFcn(app, @editMissionNotesMenu_Callback, true);
            app.editMissionNotesMenu.Tooltip = {'Opens a dialog box that allows you to write notes about the mission and save them.'};
            app.editMissionNotesMenu.Separator = 'on';
            app.editMissionNotesMenu.Text = 'Mission Notes';
            app.editMissionNotesMenu.Tag = 'editMissionNotesMenu';
            app.viewMenu = uimenu(app.ma_LvdMainGUI);
            app.viewMenu.MenuSelectedFcn = createCallbackFcn(app, @viewMenu_Callback, true);
            app.viewMenu.Text = 'View';
            app.viewMenu.Tag = 'viewMenu';
            app.editViewSettingsMenu = uimenu(app.viewMenu);
            app.editViewSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @editViewSettingsMenu_Callback, true);
            app.editViewSettingsMenu.Tooltip = {'Opens the View Settings dialog box, which contains extensive options for displaying the mission trajectory in the display area. '};
            app.editViewSettingsMenu.Text = 'Edit View Settings';
            app.editViewSettingsMenu.Tag = 'editViewSettingsMenu';
            app.setActiveViewProfileMenu = uimenu(app.viewMenu);
            app.setActiveViewProfileMenu.MenuSelectedFcn = createCallbackFcn(app, @setActiveViewProfileMenuSelected, true);
            app.setActiveViewProfileMenu.Text = 'Select Active View Profile';
            app.setActiveViewProfileMenu.Tag = 'setActiveViewProfileMenu';
            app.popOutOrbitDisplayMenu = uimenu(app.viewMenu);
            app.popOutOrbitDisplayMenu.MenuSelectedFcn = createCallbackFcn(app, @popOutOrbitDisplayMenu_Callback, true);
            app.popOutOrbitDisplayMenu.Tooltip = {'Pops out the orbit display to a new figure window.'};
            app.popOutOrbitDisplayMenu.Separator = 'on';
            app.popOutOrbitDisplayMenu.Text = 'Pop Out Orbit Display';
            app.popOutOrbitDisplayMenu.Tag = 'popOutOrbitDisplayMenu';
            app.scenarioMenu = uimenu(app.ma_LvdMainGUI);
            app.scenarioMenu.Text = 'Scenario';
            app.scenarioMenu.Tag = 'scenarioMenu';
            app.editLaunchVehicleMenu = uimenu(app.scenarioMenu);
            app.editLaunchVehicleMenu.MenuSelectedFcn = createCallbackFcn(app, @editLaunchVehicleMenu_Callback, true);
            app.editLaunchVehicleMenu.Tooltip = {'Edits the vehicle configuration, including stages, tanks, engines, and electrical components.'};
            app.editLaunchVehicleMenu.Text = 'Edit Vehicle Configuration';
            app.editLaunchVehicleMenu.Tag = 'editLaunchVehicleMenu';
            app.editInitialStateMenu = uimenu(app.scenarioMenu);
            app.editInitialStateMenu.MenuSelectedFcn = createCallbackFcn(app, @editInitialStateMenu_Callback, true);
            app.editInitialStateMenu.Tooltip = {'Edits the initial time, orbit, steering, throttle, and other state parameters.'};
            app.editInitialStateMenu.Text = 'Edit Initial State';
            app.editInitialStateMenu.Tag = 'editInitialStateMenu';
            app.editStopwatchesMenu = uimenu(app.scenarioMenu);
            app.editStopwatchesMenu.MenuSelectedFcn = createCallbackFcn(app, @editStopwatchesMenu_Callback, true);
            app.editStopwatchesMenu.Tooltip = {'Stopwatches are timers that can be used to time how much time has elapsed since it started running.'};
            app.editStopwatchesMenu.Separator = 'on';
            app.editStopwatchesMenu.Text = 'Edit Stopwatches';
            app.editStopwatchesMenu.Tag = 'editStopwatchesMenu';
            app.editExtremaMenu = uimenu(app.scenarioMenu);
            app.editExtremaMenu.MenuSelectedFcn = createCallbackFcn(app, @editExtremaMenu_Callback, true);
            app.editExtremaMenu.Tooltip = {'Extrema are minimums and maximums of a quantity over the course of the mission.'};
            app.editExtremaMenu.Text = 'Edit Extrema';
            app.editExtremaMenu.Tag = 'editExtremaMenu';
            app.editCalculusObjectsMenu = uimenu(app.scenarioMenu);
            app.editCalculusObjectsMenu.MenuSelectedFcn = createCallbackFcn(app, @editCalculusObjectsMenu_Callback, true);
            app.editCalculusObjectsMenu.Tooltip = {'Calculus calculations are used to compute derivatives and integrals of quantities.'};
            app.editCalculusObjectsMenu.Text = 'Edit Calculus Calculations';
            app.editCalculusObjectsMenu.Tag = 'editCalculusObjectsMenu';
            app.editGroundObjsMenu = uimenu(app.scenarioMenu);
            app.editGroundObjsMenu.MenuSelectedFcn = createCallbackFcn(app, @editGroundObjsMenu_Callback, true);
            app.editGroundObjsMenu.Tooltip = {'Ground objects reprsent buildings, vehicles, aircraft, or anything that is fixed or moves relative to the surface of a celestial body.'};
            app.editGroundObjsMenu.Separator = 'on';
            app.editGroundObjsMenu.Text = 'Edit Ground Objects';
            app.editGroundObjsMenu.Tag = 'editGroundObjsMenu';
            app.SensorsMenu = uimenu(app.scenarioMenu);
            app.SensorsMenu.Separator = 'on';
            app.SensorsMenu.Text = 'Sensors';
            app.EditSensorsMenu = uimenu(app.SensorsMenu);
            app.EditSensorsMenu.MenuSelectedFcn = createCallbackFcn(app, @EditSensorsMenuSelected, true);
            app.EditSensorsMenu.Tooltip = {'Sensors are objects that can detect sensor targets.'};
            app.EditSensorsMenu.Text = 'Edit Sensors';
            app.EditSensorTargetsMenu = uimenu(app.SensorsMenu);
            app.EditSensorTargetsMenu.MenuSelectedFcn = createCallbackFcn(app, @EditSensorTargetsMenuSelected, true);
            app.EditSensorTargetsMenu.Tooltip = {'Sensor targets are objects that can be detected by sensors. '};
            app.EditSensorTargetsMenu.Text = 'Edit Sensor Targets';
            app.SensorReportsMenu = uimenu(app.SensorsMenu);
            app.SensorReportsMenu.MenuSelectedFcn = createCallbackFcn(app, @SensorReportsMenuSelected, true);
            app.SensorReportsMenu.Tooltip = {'Sensor reports are used to determine when sensors detect sensor targets over the course of the mission.'};
            app.SensorReportsMenu.Separator = 'on';
            app.SensorReportsMenu.Text = 'Sensor Reports';
            app.geometryMenu = uimenu(app.scenarioMenu);
            app.geometryMenu.Tooltip = {'The geometry system allows uses to construct points, vectors, and more for use in the mission.  Particularly useful is the ability to define custom reference frames.'};
            app.geometryMenu.Separator = 'on';
            app.geometryMenu.Text = 'Geometry';
            app.geometryMenu.Tag = 'geometryMenu';
            app.editPointsMenu = uimenu(app.geometryMenu);
            app.editPointsMenu.MenuSelectedFcn = createCallbackFcn(app, @editPointsMenu_Callback, true);
            app.editPointsMenu.Tooltip = {'Geometric points are objects that have position and velocity external to the spacecraft being modeled.'};
            app.editPointsMenu.Text = 'Edit Points';
            app.editPointsMenu.Tag = 'editPointsMenu';
            app.editVectorsMenu = uimenu(app.geometryMenu);
            app.editVectorsMenu.MenuSelectedFcn = createCallbackFcn(app, @editVectorsMenu_Callback, true);
            app.editVectorsMenu.Tooltip = {'Geometric vectors have an origin, direction, and magnitude, and can be derived from a variety of sources.'};
            app.editVectorsMenu.Text = 'Edit Vectors';
            app.editVectorsMenu.Tag = 'editVectorsMenu';
            app.editAnglesMenu = uimenu(app.geometryMenu);
            app.editAnglesMenu.MenuSelectedFcn = createCallbackFcn(app, @editAnglesMenu_Callback, true);
            app.editAnglesMenu.Tooltip = {'Angles represent the angle between vectors or planes.'};
            app.editAnglesMenu.Text = 'Edit Angles';
            app.editAnglesMenu.Tag = 'editAnglesMenu';
            app.editPlanesMenu = uimenu(app.geometryMenu);
            app.editPlanesMenu.MenuSelectedFcn = createCallbackFcn(app, @editPlanesMenu_Callback, true);
            app.editPlanesMenu.Tooltip = {'A plane is an infinite, flat object centered at a point with a particular normal direction.'};
            app.editPlanesMenu.Text = 'Edit Planes';
            app.editPlanesMenu.Tag = 'editPlanesMenu';
            app.editCoordSysMenu = uimenu(app.geometryMenu);
            app.editCoordSysMenu.MenuSelectedFcn = createCallbackFcn(app, @editCoordSysMenu_Callback, true);
            app.editCoordSysMenu.Tooltip = {'Coordinate systems are a right-handed, orthonormal triad.  They do not have origins.'};
            app.editCoordSysMenu.Text = 'Edit Coordinate Systems';
            app.editCoordSysMenu.Tag = 'editCoordSysMenu';
            app.editRefRamesMenu = uimenu(app.geometryMenu);
            app.editRefRamesMenu.MenuSelectedFcn = createCallbackFcn(app, @editRefRamesMenu_Callback, true);
            app.editRefRamesMenu.Tooltip = {'Reference frames pair a coordinate system with an origin point.'};
            app.editRefRamesMenu.Text = 'Edit Reference Frames';
            app.editRefRamesMenu.Tag = 'editRefRamesMenu';
            app.simulationMenu = uimenu(app.ma_LvdMainGUI);
            app.simulationMenu.Text = 'Simulation';
            app.simulationMenu.Tag = 'simulationMenu';
            app.runScriptMenu = uimenu(app.simulationMenu);
            app.runScriptMenu.MenuSelectedFcn = createCallbackFcn(app, @runScriptMenu_Callback, true);
            app.runScriptMenu.Tooltip = {'Runs the script once and generates output.'};
            app.runScriptMenu.Accelerator = 'P';
            app.runScriptMenu.Text = 'Run Script';
            app.runScriptMenu.Tag = 'runScriptMenu';
            app.createkOSExecCodeMenu = uimenu(app.simulationMenu);
            app.createkOSExecCodeMenu.MenuSelectedFcn = createCallbackFcn(app, @createkOSExecCodeMenu_Callback, true);
            app.createkOSExecCodeMenu.Tooltip = {'Creates a CSV file that can be read in with the kOS script that ships with KSPTOT, allowing kOS to execute the LVD mission plan.'};
            app.createkOSExecCodeMenu.Separator = 'on';
            app.createkOSExecCodeMenu.Text = 'Create kOS Control CSV File';
            app.createkOSExecCodeMenu.Tag = 'createkOSExecCodeMenu';
            app.RunCaseMatrixMenu = uimenu(app.simulationMenu);
            app.RunCaseMatrixMenu.MenuSelectedFcn = createCallbackFcn(app, @RunCaseMatrixMenuSelected, true);
            app.RunCaseMatrixMenu.Tooltip = {'Runs a "matrix" of mission cases, using the open case as a template.  Each case is a unique combination of parameters, and parameters are defined as plugin variables.'};
            app.RunCaseMatrixMenu.Separator = 'on';
            app.RunCaseMatrixMenu.Text = 'Run Case Matrix';
            app.optimizationMenu = uimenu(app.ma_LvdMainGUI);
            app.optimizationMenu.Text = 'Optimization';
            app.optimizationMenu.Tag = 'optimizationMenu';
            app.editObjFunctionMenu = uimenu(app.optimizationMenu);
            app.editObjFunctionMenu.MenuSelectedFcn = createCallbackFcn(app, @editObjFunctionMenu_Callback, true);
            app.editObjFunctionMenu.Tooltip = {'Defines the objective function to be optimized.'};
            app.editObjFunctionMenu.Text = 'Edit Objective Function';
            app.editObjFunctionMenu.Tag = 'editObjFunctionMenu';
            app.editConstraintsMenu = uimenu(app.optimizationMenu);
            app.editConstraintsMenu.MenuSelectedFcn = createCallbackFcn(app, @editConstraintsMenu_Callback, true);
            app.editConstraintsMenu.Tooltip = {'Edit the constraints on the mission.'};
            app.editConstraintsMenu.Text = 'Edit Constraints';
            app.editConstraintsMenu.Tag = 'editConstraintsMenu';
            app.perturbOptVarsMenu = uimenu(app.optimizationMenu);
            app.perturbOptVarsMenu.MenuSelectedFcn = createCallbackFcn(app, @perturbOptVarsMenu_Callback, true);
            app.perturbOptVarsMenu.Tooltip = {'Use this to perturn the optimization variables a small amount.  Useful for getting missions out of the local minima they are stuck in.'};
            app.perturbOptVarsMenu.Separator = 'on';
            app.perturbOptVarsMenu.Text = 'Perturb Optimization Variables';
            app.perturbOptVarsMenu.Tag = 'perturbOptVarsMenu';
            app.adjustVariablesMenu = uimenu(app.optimizationMenu);
            app.adjustVariablesMenu.MenuSelectedFcn = createCallbackFcn(app, @adjustVariablesMenu_Callback, true);
            app.adjustVariablesMenu.Tooltip = {'Use this to adjust optimization variables in real time with display updates.'};
            app.adjustVariablesMenu.Text = 'Adjust Variables';
            app.adjustVariablesMenu.Tag = 'adjustVariablesMenu';
            app.showConstrJacobianHeatMapMenu = uimenu(app.optimizationMenu);
            app.showConstrJacobianHeatMapMenu.MenuSelectedFcn = createCallbackFcn(app, @showConstrJacobianHeatMapMenu_Callback, true);
            app.showConstrJacobianHeatMapMenu.Tooltip = {'Computes and shows a heat map representing the magnitude of the elements of the variables/constraints Jacobian matrix.'};
            app.showConstrJacobianHeatMapMenu.Separator = 'on';
            app.showConstrJacobianHeatMapMenu.Text = 'Show Constraint Jacobian Heat Map';
            app.showConstrJacobianHeatMapMenu.Tag = 'showConstrJacobianHeatMapMenu';
            app.ScaleContraintsbyMenu = uimenu(app.optimizationMenu);
            app.ScaleContraintsbyMenu.Text = 'Scale Contraints by...';
            app.ScaleConstraintsCurrentValueMenu = uimenu(app.ScaleContraintsbyMenu);
            app.ScaleConstraintsCurrentValueMenu.MenuSelectedFcn = createCallbackFcn(app, @ScaleConstraintsCurrentValueMenuSelected, true);
            app.ScaleConstraintsCurrentValueMenu.Tooltip = {'Automatically sets the scale factor of each constraint by their current constraint value, rounded up to the nearest power of 10.'};
            app.ScaleConstraintsCurrentValueMenu.Text = 'Current Value';
            app.ScaleConstraintsJacobianMenu = uimenu(app.ScaleContraintsbyMenu);
            app.ScaleConstraintsJacobianMenu.MenuSelectedFcn = createCallbackFcn(app, @ScaleConstraintsJacobianMenuSelected, true);
            app.ScaleConstraintsJacobianMenu.Tooltip = {'Automatically sets the scale factor of each constraint by largest value for that constraint in the Jacobian matrix, rounded up to the nearest power of 10.'};
            app.ScaleConstraintsJacobianMenu.Text = 'Jacobian';
            app.optimizeMissionMenu = uimenu(app.optimizationMenu);
            app.optimizeMissionMenu.MenuSelectedFcn = createCallbackFcn(app, @optimizeMissionMenu_Callback, true);
            app.optimizeMissionMenu.Tooltip = {'Adjusts variable values to optimize the objective function while meeting constraints.'};
            app.optimizeMissionMenu.Separator = 'on';
            app.optimizeMissionMenu.Accelerator = 'O';
            app.optimizeMissionMenu.Text = 'Optimize Mission';
            app.optimizeMissionMenu.Tag = 'optimizeMissionMenu';
            app.pluginsMenu = uimenu(app.ma_LvdMainGUI);
            app.pluginsMenu.Text = 'Plugins';
            app.pluginsMenu.Tag = 'pluginsMenu';
            app.managePluginsMenu = uimenu(app.pluginsMenu);
            app.managePluginsMenu.MenuSelectedFcn = createCallbackFcn(app, @managePluginsMenu_Callback, true);
            app.managePluginsMenu.Tooltip = {'Creates, edits, and deletes plugins.  Plugins are user-written code that can be used to generate behavior or create objective functions or contraints not already in LVD.'};
            app.managePluginsMenu.Text = 'Manage Plugins';
            app.managePluginsMenu.Tag = 'managePluginsMenu';
            app.PluginVariablesMenu = uimenu(app.pluginsMenu);
            app.PluginVariablesMenu.MenuSelectedFcn = createCallbackFcn(app, @PluginVariablesMenuSelected, true);
            app.PluginVariablesMenu.Tooltip = {'Creates, edits, or deletes plugin variables.  Plugin variables are optimizable quantities that plugins can use directly.'};
            app.PluginVariablesMenu.Separator = 'on';
            app.PluginVariablesMenu.Text = 'Plugin Variables';
            app.settingsMenu = uimenu(app.ma_LvdMainGUI);
            app.settingsMenu.MenuSelectedFcn = createCallbackFcn(app, @settingsMenu_Callback, true);
            app.settingsMenu.Text = 'Settings';
            app.settingsMenu.Tag = 'settingsMenu';
            app.integrationSettingsMenu = uimenu(app.settingsMenu);
            app.integrationSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @integrationSettingsMenu_Callback, true);
            app.integrationSettingsMenu.Text = 'Integration Settings';
            app.integrationSettingsMenu.Tag = 'integrationSettingsMenu';
            app.intMinAltitudeMenu = uimenu(app.integrationSettingsMenu);
            app.intMinAltitudeMenu.MenuSelectedFcn = createCallbackFcn(app, @intMinAltitudeMenu_Callback, true);
            app.intMinAltitudeMenu.Tooltip = {'Sets the minimum altitude the vehicle is allowed to go to relative to the body at the center of each sphere of influence.'};
            app.intMinAltitudeMenu.Text = 'Minimum Altitude';
            app.intMinAltitudeMenu.Tag = 'intMinAltitudeMenu';
            app.intMaxSimTimeMenu = uimenu(app.integrationSettingsMenu);
            app.intMaxSimTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @intMaxSimTimeMenu_Callback, true);
            app.intMaxSimTimeMenu.Tooltip = {'The maximum amount of in-simulation time to allow the mission to run.'};
            app.intMaxSimTimeMenu.Text = 'Max. Simulation Time';
            app.intMaxSimTimeMenu.Tag = 'intMaxSimTimeMenu';
            app.maxScriptPropTimeMenu = uimenu(app.integrationSettingsMenu);
            app.maxScriptPropTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @maxScriptPropTimeMenu_Callback, true);
            app.maxScriptPropTimeMenu.Tooltip = {'How much real life wall clock time the mission script should be propagated for before terminating.'};
            app.maxScriptPropTimeMenu.Text = 'Max. Script Propagation Time';
            app.maxScriptPropTimeMenu.Tag = 'maxScriptPropTimeMenu';
            app.sparseIntegratorOutputMenu = uimenu(app.integrationSettingsMenu);
            app.sparseIntegratorOutputMenu.MenuSelectedFcn = createCallbackFcn(app, @sparseIntegratorOutputMenu_Callback, true);
            app.sparseIntegratorOutputMenu.Tooltip = {'Causes the integrator to only return the first and last state of each event.'};
            app.sparseIntegratorOutputMenu.Text = 'Sparse Integrator Output';
            app.sparseIntegratorOutputMenu.Tag = 'sparseIntegratorOutputMenu';
            app.setIntegrationStepSizeForAllEventsMenu = uimenu(app.integrationSettingsMenu);
            app.setIntegrationStepSizeForAllEventsMenu.MenuSelectedFcn = createCallbackFcn(app, @setIntegrationStepSizeForAllEventsMenuSelected, true);
            app.setIntegrationStepSizeForAllEventsMenu.Tooltip = {'Useful for setting the step size of each event to the same value.'};
            app.setIntegrationStepSizeForAllEventsMenu.Separator = 'on';
            app.setIntegrationStepSizeForAllEventsMenu.Text = 'Set Integration Step Size for All Events';
            app.setIntegrationStepSizeForAllEventsMenu.Tag = 'setIntegrationStepSizeForAllEventsMenu';
            app.optimSettingsMenu = uimenu(app.settingsMenu);
            app.optimSettingsMenu.Text = 'Optimization Settings';
            app.optimSettingsMenu.Tag = 'optimSettingsMenu';
            app.selectOptimizationAlgosMenu = uimenu(app.optimSettingsMenu);
            app.selectOptimizationAlgosMenu.MenuSelectedFcn = createCallbackFcn(app, @selectOptimizationAlgosMenu_Callback, true);
            app.selectOptimizationAlgosMenu.Tooltip = {'Sets optimizer algorithms and gradient calculation algorithms.'};
            app.selectOptimizationAlgosMenu.Text = 'Select Optimization Algorithms';
            app.selectOptimizationAlgosMenu.Tag = 'selectOptimizationAlgosMenu';
            app.autopropagateMenu = uimenu(app.settingsMenu);
            app.autopropagateMenu.MenuSelectedFcn = createCallbackFcn(app, @autopropagateMenu_Callback, true);
            app.autopropagateMenu.Tooltip = {'When this is checked, the script will auto-propagate after a change is made to the mission case in some way.'};
            app.autopropagateMenu.Separator = 'on';
            app.autopropagateMenu.Checked = 'on';
            app.autopropagateMenu.Text = 'Auto-Propagate Script';
            app.autopropagateMenu.Tag = 'autopropagateMenu';
            app.displayEvtPropTimesInLogFileMenu = uimenu(app.settingsMenu);
            app.displayEvtPropTimesInLogFileMenu.MenuSelectedFcn = createCallbackFcn(app, @displayEvtPropTimesInLogFileMenu_Callback, true);
            app.displayEvtPropTimesInLogFileMenu.Tooltip = {'Displays data about the script execution in the ksptot.log file, including how much time it takes to execute each event.'};
            app.displayEvtPropTimesInLogFileMenu.Text = 'Display Event Propagation Times in KSPTOT Log File';
            app.displayEvtPropTimesInLogFileMenu.Tag = 'displayEvtPropTimesInLogFileMenu';
            app.configureCelestialBodyStateCacheMenu = uimenu(app.settingsMenu);
            app.configureCelestialBodyStateCacheMenu.MenuSelectedFcn = createCallbackFcn(app, @configureCelestialBodyStateCacheMenu_Callback, true);
            app.configureCelestialBodyStateCacheMenu.Tooltip = {'When using numerically integrated celestial bodies, use this to manipulate the cache of their states internally.'};
            app.configureCelestialBodyStateCacheMenu.Separator = 'on';
            app.configureCelestialBodyStateCacheMenu.Text = 'Configure Celestial Body State Cache';
            app.configureCelestialBodyStateCacheMenu.Tag = 'configureCelestialBodyStateCacheMenu';
            app.toolsMenu = uimenu(app.ma_LvdMainGUI);
            app.toolsMenu.Text = 'Tools';
            app.toolsMenu.Tag = 'toolsMenu';
            app.astroCalculatorsMenu = uimenu(app.toolsMenu);
            app.astroCalculatorsMenu.MenuSelectedFcn = createCallbackFcn(app, @astroCalculatorsMenu_Callback, true);
            app.astroCalculatorsMenu.Tooltip = {'Calculators for basic astrodynamics calculations.'};
            app.astroCalculatorsMenu.Text = 'Astrodynamics Calculators';
            app.astroCalculatorsMenu.Tag = 'astroCalculatorsMenu';
            app.haloOrbitConstructorMenu = uimenu(app.toolsMenu);
            app.haloOrbitConstructorMenu.MenuSelectedFcn = createCallbackFcn(app, @haloOrbitConstructorMenu_Callback, true);
            app.haloOrbitConstructorMenu.Tooltip = {'Use this to construct 3-body halo orbits and determine initial states for use in LVD.'};
            app.haloOrbitConstructorMenu.Text = 'Halo Orbit Constructor';
            app.haloOrbitConstructorMenu.Tag = 'haloOrbitConstructorMenu';
            app.graphicalAnalysisMenu = uimenu(app.toolsMenu);
            app.graphicalAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @graphicalAnalysisMenu_Callback, true);
            app.graphicalAnalysisMenu.Tooltip = {'Graphical Analysis allows the user to plot data from the mission script and export it to a CSV file. '};
            app.graphicalAnalysisMenu.Separator = 'on';
            app.graphicalAnalysisMenu.Text = 'Graphical Analysis';
            app.graphicalAnalysisMenu.Tag = 'graphicalAnalysisMenu';
            app.celBodyCatalogMenu = uimenu(app.toolsMenu);
            app.celBodyCatalogMenu.MenuSelectedFcn = createCallbackFcn(app, @celBodyCatalogMenu_Callback, true);
            app.celBodyCatalogMenu.Tooltip = {'Displays data on the celestial bodies loaded in the mission case.'};
            app.celBodyCatalogMenu.Separator = 'on';
            app.celBodyCatalogMenu.Text = 'Celestial Body Catalog';
            app.celBodyCatalogMenu.Tag = 'celBodyCatalogMenu';
            app.LVDConsoleMenu = uimenu(app.toolsMenu);
            app.LVDConsoleMenu.MenuSelectedFcn = createCallbackFcn(app, @LVDConsoleMenuSelected, true);
            app.LVDConsoleMenu.Separator = 'on';
            app.LVDConsoleMenu.Text = 'LVD Console';
            app.uitoolbar1 = uitoolbar(app.ma_LvdMainGUI);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.newMissionPlanToolbar = uipushtool(app.uitoolbar1);
            app.newMissionPlanToolbar.Tag = 'newMissionPlanToolbar';
            app.newMissionPlanToolbar.Tooltip = 'New Mission Plan';
            app.newMissionPlanToolbar.ClickedCallback = createCallbackFcn(app, @newMissionPlanToolbar_ClickedCallback, true);
            app.newMissionPlanToolbar.Icon = 'new-document.png';
            app.openMissionPlanToolbar = uipushtool(app.uitoolbar1);
            app.openMissionPlanToolbar.Tag = 'openMissionPlanToolbar';
            app.openMissionPlanToolbar.Tooltip = 'Open Mission Plan';
            app.openMissionPlanToolbar.ClickedCallback = createCallbackFcn(app, @openMissionPlanToolbar_ClickedCallback, true);
            app.openMissionPlanToolbar.Icon = 'folder.png';
            app.saveMissionPlanToolbar = uipushtool(app.uitoolbar1);
            app.saveMissionPlanToolbar.Tag = 'saveMissionPlanToolbar';
            app.saveMissionPlanToolbar.Tooltip = 'Save Mission Plan';
            app.saveMissionPlanToolbar.ClickedCallback = createCallbackFcn(app, @saveMissionPlanToolbar_ClickedCallback, true);
            app.saveMissionPlanToolbar.Icon = 'save-file.png';
            app.panPushMenuToggle = uitoggletool(app.uitoolbar1);
            app.panPushMenuToggle.Tag = 'uitoggletool1';
            app.panPushMenuToggle.Tooltip = {'Pan'};
            app.panPushMenuToggle.ClickedCallback = createCallbackFcn(app, @uitoggletool1_ClickedCallback, true);
            app.panPushMenuToggle.Icon = 'pan_image.png';
            app.panPushMenuToggle.Separator = 'on';
            app.rotatePushMenuToggle = uitoggletool(app.uitoolbar1);
            app.rotatePushMenuToggle.Tag = 'uitoggletool2';
            app.rotatePushMenuToggle.Tooltip = {'Rotate 3D'};
            app.rotatePushMenuToggle.ClickedCallback = createCallbackFcn(app, @uitoggletool2_ClickedCallback, true);
            app.rotatePushMenuToggle.Icon = '3d_rotate.png';
            app.zoomOutPushMenuToggle = uitoggletool(app.uitoolbar1);
            app.zoomOutPushMenuToggle.Tag = 'uitoggletool3';
            app.zoomOutPushMenuToggle.Tooltip = {'Zoom In/Out'};
            app.zoomOutPushMenuToggle.ClickedCallback = createCallbackFcn(app, @uitoggletool3_ClickedCallback, true);
            app.zoomOutPushMenuToggle.Icon = 'magnifying-glass-2.png';
            app.zoomInPushMenuToggle = uitoggletool(app.uitoolbar1);
            app.zoomInPushMenuToggle.Tag = 'uitoggletool4';
            app.zoomInPushMenuToggle.Tooltip = {'Camera Forward/Backward'};
            app.zoomInPushMenuToggle.ClickedCallback = createCallbackFcn(app, @uitoggletool4_ClickedCallback, true);
            app.zoomInPushMenuToggle.Icon = 'in_out_arrow.png';
            app.RestoreViewToolbarMenu = uipushtool(app.uitoolbar1);
            app.RestoreViewToolbarMenu.Tooltip = {'Restore View'};
            app.RestoreViewToolbarMenu.ClickedCallback = createCallbackFcn(app, @RestoreViewToolbarMenuClicked, true);
            app.RestoreViewToolbarMenu.Icon = 'refresh.png';
            app.GridLayout16 = uigridlayout(app.ma_LvdMainGUI);
            app.Panel = uipanel(app.GridLayout16);
            app.GridLayout17 = uigridlayout(app.Panel);
            app.BusyStatusIcon = uiimage(app.GridLayout17);
            app.BusyStatusIcon.Visible = 'off';
            app.BusyStatusIcon.ImageSource = 'Spin-1s-200px_transparentBg.gif';
            app.BusyStatusLabel = uilabel(app.GridLayout17);
            app.BusyStatusLabel.Visible = 'off';
            app.GridLayout = uigridlayout(app.GridLayout16);
            app.GridLayout19 = uigridlayout(app.GridLayout);
            app.titleText = uilabel(app.GridLayout19);
            app.titleText.Tag = 'titleText';
            app.titleText.Text = {'KSP Trajectory Optimization Tool'; 'Launch Vehicle Designer'};
            app.Image = uiimage(app.GridLayout19);
            app.Image.ImageSource = 'launchVehicleDesigner_20230323.png';
            app.GridLayout23 = uigridlayout(app.GridLayout);
            app.DisplayPanel = uipanel(app.GridLayout23);
            app.DisplayPanel.Title = 'Display';
            app.GridLayout11 = uigridlayout(app.DisplayPanel);
            app.dispAxisTitleLabel = uilabel(app.GridLayout11);
            app.dispAxisTitleLabel.Tag = 'dispAxisTitleLabel';
            app.dispAxisTitleLabel.Text = {'Title'; 'Title'};
            app.scriptResultsOutOfDateLbl = uilabel(app.GridLayout11);
            app.scriptResultsOutOfDateLbl.Tag = 'scriptResultsOutOfDateLbl';
            app.scriptResultsOutOfDateLbl.Visible = 'off';
            app.scriptResultsOutOfDateLbl.Tooltip = 'Mission Case has been updated but script is set to not autopropagate.  In-memory results of script execution will be out of date until the script is propagated (ctrl-p).';
            app.scriptResultsOutOfDateLbl.Text = 'Script execution results out of date.';
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.timeSliderValueLabel = uilabel(app.GridLayout12);
            app.timeSliderValueLabel.Tag = 'timeSliderValueLabel';
            app.timeSliderValueLabel.Text = 'Static Text';
            app.incrOrbitToPlotNum = uibutton(app.GridLayout12, 'push');
            app.incrOrbitToPlotNum.ButtonPushedFcn = createCallbackFcn(app, @incrOrbitToPlotNum_Callback, true);
            app.incrOrbitToPlotNum.Tag = 'incrOrbitToPlotNum';
            app.incrOrbitToPlotNum.Icon = 'right-arrow.png';
            app.incrOrbitToPlotNum.Text = '';
            app.decrOrbitToPlotNum = uibutton(app.GridLayout12, 'push');
            app.decrOrbitToPlotNum.ButtonPushedFcn = createCallbackFcn(app, @decrOrbitToPlotNum_Callback, true);
            app.decrOrbitToPlotNum.Tag = 'decrOrbitToPlotNum';
            app.decrOrbitToPlotNum.Icon = 'left-arrow.png';
            app.decrOrbitToPlotNum.Tooltip = {'Jump to previous sphere of influence.'};
            app.decrOrbitToPlotNum.Text = '';
            app.DispAxesTimeSlider = uislider(app.GridLayout12);
            app.DispAxesTimeSlider.MajorTickLabels = {'0', '9', '18', '27', '36', '45', '54', '63', '72', '81', '90', '100'};
            app.DispAxesTimeSlider.Tooltip = {''};
            app.Panel_3 = uipanel(app.GridLayout11);
            app.DisplayAxesGridLayout = uigridlayout(app.Panel_3);
            app.dispAxes = uiaxes(app.DisplayAxesGridLayout);
            app.dispAxes.Tag = 'dispAxes';
            app.uipanel1 = uipanel(app.GridLayout23);
            app.uipanel1.Title = 'Output';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout13 = uigridlayout(app.uipanel1);
            app.outputText = uitextarea(app.GridLayout13);
            app.outputText.Tag = 'outputText';
            app.GridLayout3 = uigridlayout(app.GridLayout23);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.initScStatePanel = uipanel(app.GridLayout4);
            app.initScStatePanel.Title = 'Initial Spacecraft State';
            app.initScStatePanel.Tag = 'initScStatePanel';
            app.GridLayout21 = uigridlayout(app.initScStatePanel);
            app.InitialSpacecraftStateTextArea = uitextarea(app.GridLayout21);
            app.InitialSpacecraftStateTextArea.Editable = 'off';
            app.InitialSpacecraftStateTextArea.Value = {'Universal Time (UT)     ='; '  '; 'Semi-major Axis         = '; 'Eccentricity            ='; 'Inclination             ='; 'Right Asc. of Asc. Node = '; 'Argument of Periapse    ='; 'True Anomaly            ='; '   '; 'Dry Mass                ='; 'Fuel/Ox Mass            ='; 'Monopropellant Mass     ='; 'Xenon Mass              ='; 'Total Mass              ='};
            app.finalScStatePanel = uipanel(app.GridLayout4);
            app.finalScStatePanel.Title = 'Final Spacecraft State';
            app.finalScStatePanel.Tag = 'finalScStatePanel';
            app.GridLayout20 = uigridlayout(app.finalScStatePanel);
            app.FinalSpacecraftStateTextArea = uitextarea(app.GridLayout20);
            app.FinalSpacecraftStateTextArea.Editable = 'off';
            app.FinalSpacecraftStateTextArea.Value = {'Universal Time (UT)     ='; '  '; 'Semi-major Axis         = '; 'Eccentricity            ='; 'Inclination             ='; 'Right Asc. of Asc. Node = '; 'Argument of Periapse    ='; 'True Anomaly            ='; '   '; 'Dry Mass                ='; 'Fuel/Ox Mass            ='; 'Monopropellant Mass     ='; 'Xenon Mass              ='; 'Total Mass              ='};
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.SequentialEventsPanel = uipanel(app.GridLayout7);
            app.SequentialEventsPanel.Title = 'Sequential Events';
            app.GridLayout8 = uigridlayout(app.SequentialEventsPanel);
            app.insertEventButton = uibutton(app.GridLayout8, 'push');
            app.insertEventButton.ButtonPushedFcn = createCallbackFcn(app, @insertEventButton_Callback, true);
            app.insertEventButton.Tag = 'insertEventButton';
            app.insertEventButton.Icon = 'seq_event.png';
            app.insertEventButton.Text = 'Insert Sequential Event';
            app.scriptListbox = uilistbox(app.GridLayout8);
            app.scriptListbox.Items = {};
            app.scriptListbox.ValueChangedFcn = createCallbackFcn(app, @scriptListbox_Callback, true);
            app.scriptListbox.Tag = 'scriptListbox';
            app.scriptListbox.Tooltip = 'The launch vehicle mission script.';
            app.scriptListbox.ClickedFcn = createCallbackFcn(app, @scriptListboxClicked, true);
            app.scriptListbox.DoubleClickedFcn = createCallbackFcn(app, @scriptListboxDoubleClicked, true);
            app.scriptListbox.Value = {};
            app.GridLayout10 = uigridlayout(app.GridLayout8);
            app.moveEventDown = uibutton(app.GridLayout10, 'push');
            app.moveEventDown.ButtonPushedFcn = createCallbackFcn(app, @moveEventDown_Callback, true);
            app.moveEventDown.Tag = 'moveEventDown';
            app.moveEventDown.Icon = 'down-arrow.png';
            app.moveEventDown.Tooltip = 'Move selected event down in the script.';
            app.moveEventDown.Text = '';
            app.moveEventUp = uibutton(app.GridLayout10, 'push');
            app.moveEventUp.ButtonPushedFcn = createCallbackFcn(app, @moveEventUp_Callback, true);
            app.moveEventUp.Tag = 'moveEventUp';
            app.moveEventUp.Icon = 'up-arrow.png';
            app.moveEventUp.Tooltip = 'Move selected event up in the script.';
            app.moveEventUp.Text = '';
            app.deleteEvent = uibutton(app.GridLayout10, 'push');
            app.deleteEvent.ButtonPushedFcn = createCallbackFcn(app, @deleteEvent_Callback, true);
            app.deleteEvent.Tag = 'deleteEvent';
            app.deleteEvent.Icon = 'remove.png';
            app.deleteEvent.Tooltip = 'Delete selected event from script.';
            app.deleteEvent.Text = 'Delete Event';
            app.NonSequentialEventsPanel = uipanel(app.GridLayout7);
            app.NonSequentialEventsPanel.Title = 'Non-Sequential Events';
            app.GridLayout9 = uigridlayout(app.NonSequentialEventsPanel);
            app.insertNonSeqEventButton = uibutton(app.GridLayout9, 'push');
            app.insertNonSeqEventButton.ButtonPushedFcn = createCallbackFcn(app, @insertNonSeqEventButton_Callback, true);
            app.insertNonSeqEventButton.Tag = 'insertNonSeqEventButton';
            app.insertNonSeqEventButton.Icon = 'nonseq_event.png';
            app.insertNonSeqEventButton.Text = 'Insert Non-Sequential Event';
            app.deleteNonSeqEventButton = uibutton(app.GridLayout9, 'push');
            app.deleteNonSeqEventButton.ButtonPushedFcn = createCallbackFcn(app, @deleteNonSeqEventButton_Callback, true);
            app.deleteNonSeqEventButton.Tag = 'deleteNonSeqEventButton';
            app.deleteNonSeqEventButton.Icon = 'remove.png';
            app.deleteNonSeqEventButton.Tooltip = 'Delete selected event from non-sequential events list.';
            app.deleteNonSeqEventButton.Text = 'Delete Non-Sequential Event';
            app.nonSeqEventsListbox = uilistbox(app.GridLayout9);
            app.nonSeqEventsListbox.Items = {};
            app.nonSeqEventsListbox.ValueChangedFcn = createCallbackFcn(app, @nonSeqEventsListbox_Callback, true);
            app.nonSeqEventsListbox.Tag = 'nonSeqEventsListbox';
            app.nonSeqEventsListbox.Tooltip = 'Non-sequential events are events that can occur at any time during the mission.';
            app.nonSeqEventsListbox.ClickedFcn = createCallbackFcn(app, @nonSeqEventsListboxClicked, true);
            app.nonSeqEventsListbox.DoubleClickedFcn = createCallbackFcn(app, @nonSeqEventsListboxDoubleClicked, true);
            app.nonSeqEventsListbox.Value = {};
            app.WarningsAlertsPanel = uipanel(app.GridLayout23);
            app.WarningsAlertsPanel.Title = 'Warnings & Alerts';
            app.GridLayout24 = uigridlayout(app.WarningsAlertsPanel);
            app.WarnAlertTable = uitable(app.GridLayout24);
            app.WarnAlertTable.ColumnName = '';
            app.WarnAlertTable.RowName = {};
            app.WarnAlertTable.Multiselect = 'off';
            app.scriptListboxContextMenu = uicontextmenu(app.ma_LvdMainGUI);
            app.scriptListboxContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @scriptListboxContextMenuOpening, true);
            app.scriptListboxContextMenu.Tag = 'scriptListboxContextMenu';
            app.EditEventMenu = uimenu(app.scriptListboxContextMenu);
            app.EditEventMenu.MenuSelectedFcn = createCallbackFcn(app, @EditEventMenuSelected, true);
            app.EditEventMenu.Text = 'Edit Event';
            app.toggleOptimForSelEventMenu = uimenu(app.scriptListboxContextMenu);
            app.toggleOptimForSelEventMenu.MenuSelectedFcn = createCallbackFcn(app, @toggleOptimForSelEventMenu_Callback, true);
            app.toggleOptimForSelEventMenu.Separator = 'on';
            app.toggleOptimForSelEventMenu.Text = 'Toggle Optimization for Selected Event (Cntrl-F)';
            app.toggleOptimForSelEventMenu.Tag = 'toggleOptimForSelEventMenu';
            app.viewStateAfterSelectedEventMenu = uimenu(app.scriptListboxContextMenu);
            app.viewStateAfterSelectedEventMenu.MenuSelectedFcn = createCallbackFcn(app, @viewStateAfterSelectedEventMenu_Callback, true);
            app.viewStateAfterSelectedEventMenu.Separator = 'on';
            app.viewStateAfterSelectedEventMenu.Text = 'View State After Selected Event';
            app.viewStateAfterSelectedEventMenu.Tag = 'viewStateAfterSelectedEventMenu';
            app.createContConstraintsWithSelEvtMenu = uimenu(app.scriptListboxContextMenu);
            app.createContConstraintsWithSelEvtMenu.MenuSelectedFcn = createCallbackFcn(app, @createContConstraintsWithSelEvtMenu_Callback, true);
            app.createContConstraintsWithSelEvtMenu.Separator = 'on';
            app.createContConstraintsWithSelEvtMenu.Text = 'Create Continuity Constraints with Selected Event';
            app.createContConstraintsWithSelEvtMenu.Tag = 'createContConstraintsWithSelEvtMenu';
            app.copyUtAtStartOfSelEventMenu = uimenu(app.scriptListboxContextMenu);
            app.copyUtAtStartOfSelEventMenu.MenuSelectedFcn = createCallbackFcn(app, @copyUtAtStartOfSelEventMenu_Callback, true);
            app.copyUtAtStartOfSelEventMenu.Separator = 'on';
            app.copyUtAtStartOfSelEventMenu.Text = 'Copy UT at Start of Selected Event';
            app.copyUtAtStartOfSelEventMenu.Tag = 'copyUtAtStartOfSelEventMenu';
            app.copyUtAtEndOfSelEventMenu = uimenu(app.scriptListboxContextMenu);
            app.copyUtAtEndOfSelEventMenu.MenuSelectedFcn = createCallbackFcn(app, @copyUtAtEndOfSelEventMenu_Callback, true);
            app.copyUtAtEndOfSelEventMenu.Text = 'Copy UT at End of Selected Event';
            app.copyUtAtEndOfSelEventMenu.Tag = 'copyUtAtEndOfSelEventMenu';
            app.copyDurationOfSelEventMenu = uimenu(app.scriptListboxContextMenu);
            app.copyDurationOfSelEventMenu.MenuSelectedFcn = createCallbackFcn(app, @copyDurationOfSelEventMenu_Callback, true);
            app.copyDurationOfSelEventMenu.Text = 'Copy Duration (sec) of Selected Event';
            app.copyDurationOfSelEventMenu.Tag = 'copyDurationOfSelEventMenu';
            app.copyOrbitAfterSelectedEventMenu = uimenu(app.scriptListboxContextMenu);
            app.copyOrbitAfterSelectedEventMenu.MenuSelectedFcn = createCallbackFcn(app, @copyOrbitAfterSelectedEventMenu_Callback, true);
            app.copyOrbitAfterSelectedEventMenu.Text = 'Copy Orbit After Selected Event';
            app.copyOrbitAfterSelectedEventMenu.Tag = 'copyOrbitAfterSelectedEventMenu';
            app.advanceScriptToSelectedEventMenu = uimenu(app.scriptListboxContextMenu);
            app.advanceScriptToSelectedEventMenu.MenuSelectedFcn = createCallbackFcn(app, @advanceScriptToSelectedEventMenu_Callback, true);
            app.advanceScriptToSelectedEventMenu.Separator = 'on';
            app.advanceScriptToSelectedEventMenu.Text = 'Advance Script to Selected Event';
            app.advanceScriptToSelectedEventMenu.Tag = 'advanceScriptToSelectedEventMenu';
            app.ConvertImpulsiveDVActionintoFiniteBurnMenu = uimenu(app.scriptListboxContextMenu);
            app.ConvertImpulsiveDVActionintoFiniteBurnMenu.MenuSelectedFcn = createCallbackFcn(app, @ConvertImpulsiveDVActionintoFiniteBurnMenuSelected, true);
            app.ConvertImpulsiveDVActionintoFiniteBurnMenu.Separator = 'on';
            app.ConvertImpulsiveDVActionintoFiniteBurnMenu.Text = 'Convert Impulsive DV Action into Finite Burn';
            app.uploadImpDvActionToKspMenu = uimenu(app.scriptListboxContextMenu);
            app.uploadImpDvActionToKspMenu.MenuSelectedFcn = createCallbackFcn(app, @uploadImpDvActionToKspMenu_Callback, true);
            app.uploadImpDvActionToKspMenu.Text = 'Upload Impulsive DV Action to KSP';
            app.uploadImpDvActionToKspMenu.Tag = 'uploadImpDvActionToKspMenu';
            % Assign app.scriptListboxContextMenu
            app.scriptListbox.ContextMenu = app.scriptListboxContextMenu;
            app.outputContextMenu = uicontextmenu(app.ma_LvdMainGUI);
            app.outputContextMenu.Tag = 'outputContextMenu';
            app.clearOutputMenu = uimenu(app.outputContextMenu);
            app.clearOutputMenu.MenuSelectedFcn = createCallbackFcn(app, @clearOutputMenu_Callback, true);
            app.clearOutputMenu.Text = 'Clear Output';
            app.clearOutputMenu.Tag = 'clearOutputMenu';
            % Assign app.outputContextMenu
            app.outputText.ContextMenu = app.outputContextMenu;
            app.InitialOrbitDisplayTextContextMenu = uicontextmenu(app.ma_LvdMainGUI);
            app.CopyInitialOrbitToClipboardMenu = uimenu(app.InitialOrbitDisplayTextContextMenu);
            app.CopyInitialOrbitToClipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyInitialOrbitToClipboardMenuSelected, true);
            app.CopyInitialOrbitToClipboardMenu.Text = 'Copy Orbit to Clipboard';
            app.ChangeInitialReferenceFrameElementSetMenu = uimenu(app.InitialOrbitDisplayTextContextMenu);
            app.ChangeInitialReferenceFrameElementSetMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeInitialReferenceFrameElementSetMenuSelected, true);
            app.ChangeInitialReferenceFrameElementSetMenu.Text = 'Change Reference Frame and Element Set';
            % Assign app.InitialOrbitDisplayTextContextMenu
            app.InitialSpacecraftStateTextArea.ContextMenu = app.InitialOrbitDisplayTextContextMenu;
            app.FinalOrbitDisplayTextContextMenu = uicontextmenu(app.ma_LvdMainGUI);
            app.CopyFinalOrbittoClipboardMenu = uimenu(app.FinalOrbitDisplayTextContextMenu);
            app.CopyFinalOrbittoClipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyFinalOrbittoClipboardMenuSelected, true);
            app.CopyFinalOrbittoClipboardMenu.Text = 'Copy Orbit to Clipboard';
            app.ChangeFinalReferenceFrameElementSetMenu = uimenu(app.FinalOrbitDisplayTextContextMenu);
            app.ChangeFinalReferenceFrameElementSetMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeFinalReferenceFrameElementSetMenuSelected, true);
            app.ChangeFinalReferenceFrameElementSetMenu.Text = 'Change Reference Frame and Element Set';
            % Assign app.FinalOrbitDisplayTextContextMenu
            app.FinalSpacecraftStateTextArea.ContextMenu = app.FinalOrbitDisplayTextContextMenu;
            % Show the figure after all components are created
            app.ma_LvdMainGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ma_LvdMainGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ma_LvdMainGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)ma_LvdMainGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ma_LvdMainGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ma_LvdMainGUI)
        end
    end
end