classdef lvd_EditCompositeObjectiveFunctionGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditCompositeObjectiveFunctionGUI  matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        uipanel1               matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        GridLayout4            matlab.ui.container.GridLayout
        uipanel2               matlab.ui.container.Panel
        GridLayout7            matlab.ui.container.GridLayout
        uipanel4               matlab.ui.container.Panel
        GridLayout13           matlab.ui.container.GridLayout
        GridLayout15           matlab.ui.container.GridLayout
        setFrameOptionsButton  matlab.ui.control.Button
        GridLayout14           matlab.ui.container.GridLayout
        refFrameTypeCombo      matlab.ui.control.DropDown
        text19                 matlab.ui.control.Label
        refFrameNameLabel      matlab.ui.control.Label
        GridLayout12           matlab.ui.container.GridLayout
        curValScaledUnitLabel  matlab.ui.control.Label
        curValScaledLabel      matlab.ui.control.Label
        text14                 matlab.ui.control.Label
        GridLayout11           matlab.ui.container.GridLayout
        curValUnitLabel        matlab.ui.control.Label
        curValueLabel          matlab.ui.control.Label
        text12                 matlab.ui.control.Label
        GridLayout10           matlab.ui.container.GridLayout
        scaleFactorText        matlab.ui.control.EditField
        text5                  matlab.ui.control.Label
        GridLayout9            matlab.ui.container.GridLayout
        eventCombo             matlab.ui.control.DropDown
        text3                  matlab.ui.control.Label
        GridLayout8            matlab.ui.container.GridLayout
        objFuncTypeLabel       matlab.ui.control.Label
        text10                 matlab.ui.control.Label
        GridLayout5            matlab.ui.container.GridLayout
        objFuncListbox         matlab.ui.control.ListBox
        GridLayout6            matlab.ui.container.GridLayout
        removeObjFuncButton    matlab.ui.control.Button
        addObjFuncButton       matlab.ui.control.Button
        uipanel3               matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        compositionTypeCombo   matlab.ui.control.DropDown
        text9                  matlab.ui.control.Label
        optDirTypeCombo        matlab.ui.control.DropDown
        text8                  matlab.ui.control.Label
        saveAndCloseButton     matlab.ui.control.Button
        text2                  matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function genObjFunc = getCurrentlySelectedObjFunc(app, handles)
            lvdData = getappdata(handles.lvd_EditCompositeObjectiveFunctionGUI,'lvdData');
            compObjFunc = lvdData.optimizer.objFcn;

            if(isempty(compObjFunc.objFcns))
                genObjFunc = GenericObjectiveFcn.empty(1,0);

            else
%                 selObjFunc = get(handles.objFuncListbox,'Value');
%                 genObjFunc = compObjFunc.getObjFuncForInd(selObjFunc);
                genObjFunc = app.objFuncListbox.Value;
            end
        end

        function bodyInfo = getSelectedBodyInfo(app, handles)
            curFrame = getappdata(handles.lvd_EditCompositeObjectiveFunctionGUI,'frame');
            bodyInfo = curFrame.getOriginBody();
        end

        function populateGUI(app, handles, lvdData)
            lvdOptim = lvdData.optimizer;
            compObjFcn = lvdOptim.objFcn;

            %if the user is on the old objective functions, we need to upgrade them
            compObjFcn = CompositeObjectiveFcn.upgradeExistingObjFuncs(compObjFcn, lvdOptim, lvdData);
            lvdOptim.objFcn = compObjFcn;

            handles.eventCombo.String = lvdData.script.getListboxStr();
            %     populateBodiesCombo(lvdData.celBodyData, handles.refCelBodyCombo, false);

%             objFuncListbox_Callback(handles.objFuncListbox, [], handles);
            app.listboxCallback(handles);

            strs = ObjFcnDirectionTypeEnum.getListBoxStr();
            handles.optDirTypeCombo.String = strs;
            handles.optDirTypeCombo.Value = ObjFcnDirectionTypeEnum.getIndForName(compObjFcn.dirType.name);

            strs = ObjFcnCompositeMethodEnum.getListBoxStr();
            handles.compositionTypeCombo.String = strs;
            handles.compositionTypeCombo.Value = ObjFcnCompositeMethodEnum.getIndForName(compObjFcn.compositeMethod.name);
        end

        function updateFrameChange(app, handles)
            lvdData = getappdata(handles.lvd_EditCompositeObjectiveFunctionGUI,'lvdData');
            celBodyData = lvdData.celBodyData;

            refEnumListBoxStr = ReferenceFrameEnum.getListBoxStr();
            selFrameTypeInd = handles.refFrameTypeCombo.Value;
            refFrameEnum = ReferenceFrameEnum.getEnumForListboxStr(refEnumListBoxStr{selFrameTypeInd});

            %             if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
            %                 refFrameEnum = newFrame.typeEnum;
            %                 app.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
            %             end

            switch refFrameEnum
                case ReferenceFrameEnum.BodyCenteredInertial
                    bodyInfo = getSelectedBodyInfo(app, handles);

                    newFrame = bodyInfo.getBodyCenteredInertialFrame();

                case ReferenceFrameEnum.BodyFixedRotating
                    bodyInfo = getSelectedBodyInfo(app, handles);

                    newFrame = bodyInfo.getBodyFixedFrame();

                case ReferenceFrameEnum.TwoBodyRotating
                    bodyInfo = getSelectedBodyInfo(app, handles);
                    if(not(isempty(bodyInfo.childrenBodyInfo)))
                        primaryBody = bodyInfo;
                        secondaryBody = bodyInfo.childrenBodyInfo(1);
                    else
                        primaryBody = bodyInfo.getParBodyInfo();
                        secondaryBody = bodyInfo;
                    end

                    originPt = TwoBodyRotatingFrameOriginEnum.Primary;

                    newFrame = TwoBodyRotatingFrame(primaryBody, secondaryBody, originPt, celBodyData);

                case ReferenceFrameEnum.UserDefined
                    numFrames = lvdData.geometry.refFrames.getNumRefFrames();
                    if(numFrames >= 1)
                        geometricFrame = AbstractGeometricRefFrame.empty(1,0);
                        for(i=1:numFrames) %#ok<*NO4LP> 
                            frame = lvdData.geometry.refFrames.getRefFrameAtInd(i);
                            if(frame.isVehDependent() == false)
                                geometricFrame = frame;
                                break;
                            end
                        end

                        if(not(isempty(geometricFrame)))
                            newFrame = UserDefinedGeometricFrame(geometricFrame, lvdData);
                        else
                            bodyInfo = getSelectedBodyInfo(app, handles);
                            newFrame = bodyInfo.getBodyCenteredInertialFrame();

                            warndlg('There are no geometric frames available which are not dependent on vehicle properties.  A body-centered inertial frame will be selected instead.');
                        end
                    else
                        bodyInfo = getSelectedBodyInfo(app, handles);
                        newFrame = bodyInfo.getBodyCenteredInertialFrame();

                        warndlg('There are no geometric frames available which are not dependent on vehicle properties.  A body-centered inertial frame will be selected instead.');
                    end

                otherwise
                    error('Unknown reference frame type: %s', string(refFrameEnum));
            end

            if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
                refFrameEnum = newFrame.typeEnum;
                handles.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
            end

            setappdata(handles.lvd_EditCompositeObjectiveFunctionGUI,'frame', newFrame);
            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', newFrame.getNameStr());
            handles.refFrameNameLabel.String = sprintf('%s', newFrame.getNameStr());
            handles.refFrameNameLabel.TooltipString = sprintf('%s', newFrame.getNameStr());
        end

        function updateValueLabels(app, handles)
            lvdData = getappdata(handles.lvd_EditCompositeObjectiveFunctionGUI,'lvdData');
            stateLog = lvdData.stateLog;
            genObjFunc = getCurrentlySelectedObjFunc(app, handles);

            if(not(isempty(genObjFunc)))
                [f, fUnscaled] = genObjFunc.evalObjFcn(stateLog);
                handles.curValueLabel.String = fullAccNum2Str(fUnscaled);
                handles.curValScaledLabel.String = fullAccNum2Str(f);

                [unit, ~, ~, ~, ~, ~] = genObjFunc.fcn.getConstraintStaticDetails();
                handles.curValUnitLabel.String = unit;
                handles.curValUnitLabel.TooltipString = unit;
            else
                handles.curValueLabel.String = ' -- ';
                handles.curValScaledLabel.String = ' -- ';
                handles.curValUnitLabel.String = '';
                handles.curValUnitLabel.TooltipString = '';
            end
        end

        function saveAndClose(app, handles)
            lvdData = getappdata(handles.lvd_EditCompositeObjectiveFunctionGUI,'lvdData');
            lvdOpt = lvdData.optimizer;
            compObjFcn = lvdOpt.objFcn;

            contents = cellstr(get(handles.optDirTypeCombo,'String'));
            str = contents{get(handles.optDirTypeCombo,'Value')};
            compObjFcn.dirType = ObjFcnDirectionTypeEnum.getEnumForListboxStr(str);

            contents = cellstr(get(handles.compositionTypeCombo,'String'));
            str = contents{get(handles.compositionTypeCombo,'Value')};
            compObjFcn.compositeMethod = ObjFcnCompositeMethodEnum.getEnumForListboxStr(str);

            app.output.output = {true};
            close(handles.lvd_EditCompositeObjectiveFunctionGUI);
        end

        function listboxCallback(app, handles)
            lvdData = getappdata(handles.lvd_EditCompositeObjectiveFunctionGUI,'lvdData');
            lvdOpt = lvdData.optimizer;
            compObjFcn = lvdOpt.objFcn;

            handles.eventCombo.String = lvdData.script.getListboxStr();

            if(isempty(compObjFcn.objFcns))
                handles.removeObjFuncButton.Enable = 'off';

                app.objFuncListbox.Items = {'<No Objective Functions>'};
                app.objFuncListbox.ItemsData = GenericObjectiveFcn.empty(1,0);

                handles.objFuncTypeLabel.String = 'None';
                handles.eventCombo.Enable = 'off';
                handles.refFrameTypeCombo.Enable = 'off';
                handles.setFrameOptionsButton.Enable = 'off';
                handles.refFrameNameLabel.String = '';
                handles.scaleFactorText.Enable = 'off';

                frame = LvdData.getDefaultInitialBodyInfo(lvdData.celBodyData).getBodyCenteredInertialFrame();
                setappdata(handles.lvd_EditCompositeObjectiveFunctionGUI,'frame',frame);
            else
                handles.removeObjFuncButton.Enable = 'on';
                handles.eventCombo.Enable = 'on';
                handles.refFrameTypeCombo.Enable = 'on';
                handles.setFrameOptionsButton.Enable = 'on';
                handles.scaleFactorText.Enable = 'on';

%                 value = get(handles.objFuncListbox,'Value');
                [listBoxStr, objFuncs] = compObjFcn.getListBoxStr();
                app.objFuncListbox.Items = listBoxStr;
                app.objFuncListbox.ItemsData = objFuncs;

%                 objFun = compObjFcn.objFcns(value);
                objFun = app.objFuncListbox.Value;
                handles.objFuncTypeLabel.String = objFun.fcn.getConstraintType();
                handles.objFuncTypeLabel.TooltipString = objFun.fcn.getConstraintType();

                eventNum = objFun.event.getEventNum();
                handles.eventCombo.Value = eventNum;

                frame = objFun.frame;
                if(isempty(frame))
                    frame = LvdData.getDefaultInitialBodyInfo(lvdData.celBodyData).getBodyCenteredInertialFrame();
                    objFun.frame = frame;
                end
                setappdata(handles.lvd_EditCompositeObjectiveFunctionGUI,'frame',frame);

                handles.refFrameTypeCombo.String = ReferenceFrameEnum.getListBoxStr();
                [ind, ~] = ReferenceFrameEnum.getIndForName(frame.typeEnum.name);
                handles.refFrameTypeCombo.Value = ind;
                handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', frame.getNameStr());
                handles.refFrameNameLabel.String = sprintf('%s', frame.getNameStr());
                handles.refFrameNameLabel.TooltipString = sprintf('%s', frame.getNameStr());

                handles.scaleFactorText.String = fullAccNum2Str(objFun.scaleFactor);
            end

            updateValueLabels(app, handles);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditCompositeObjectiveFunctionGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditCompositeObjectiveFunctionGUI (see VARARGIN)

            % Choose default command line output for lvd_EditCompositeObjectiveFunctionGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            % Update handles structure
            guidata(hObject, handles);

            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            app.output = varargin{2};

            populateGUI(app, handles, lvdData);

            % UIWAIT makes lvd_EditCompositeObjectiveFunctionGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditCompositeObjectiveFunctionGUI);
        end

        % Button pushed function: addObjFuncButton
        function addObjFuncButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addObjFuncButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditCompositeObjectiveFunctionGUI,'lvdData');
            lvdOptim = lvdData.optimizer;
%             celBodyData = lvdData.celBodyData;

            listBoxStr = ConstraintEnum.getListBoxStr();
 
%             [Selection,ok] = listdlg('PromptString','Select the objective function type:',...
%                 'SelectionMode','single',...
%                 'Name','Constraint Type',...
%                 'ListString',listBoxStr);

            out = AppDesignerGUIOutput();
            listdlgARH_App('PromptString','Select the objective function type:',...
                           'SelectionMode','single',...
                           'Name','Constraint Type',...
                           'ListString',listBoxStr, ...
                           'ListSize', [250 300], ...
                           'out',out);
            Selection = out.output{1};
            ok = out.output{2};

            if(ok == 1)
                [~, enum] = ConstraintEnum.getIndForName(listBoxStr{Selection});
                constClass = enum.class;
                input1 = enum.constructorInput1;

                newObjFunc = eval(sprintf('%s.getDefaultConstraint(%s, %s)', constClass, 'input1', 'lvdData'));

                if(not(isempty(newObjFunc)))
                    useObjFcn = newObjFunc.setupForUseAsObjectiveFcn(lvdData);

                    if(useObjFcn)
                        event = lvdData.script.getEventForInd(lvdData.script.getTotalNumOfEvents());

                        someFrame = LvdData.getDefaultInitialBodyInfo(lvdData.celBodyData).getBodyCenteredInertialFrame();
                        newObjFunc.event = event;
                        genObjFunc = GenericObjectiveFcn(event, someFrame, newObjFunc, 1, lvdOptim, lvdData);

                        lvdOptim.objFcn.addObjFunc(genObjFunc);
%                         handles.objFuncListbox.String = lvdOptim.objFcn.getListBoxStr();
%                         handles.objFuncListbox.Value = lvdOptim.objFcn.getNumberObjFuncs();
                        
                        [listBoxStr, objFuncs] = lvdOptim.objFcn.getListBoxStr();
                        app.objFuncListbox.Items = listBoxStr;
                        app.objFuncListbox.ItemsData = objFuncs;
                        app.objFuncListbox.Value = genObjFunc;
                        
%                         objFuncListbox_Callback(handles.objFuncListbox, [], handles);
                        app.listboxCallback(handles);
                    end
                end
            end
        end

        % Value changed function: eventCombo
        function eventCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to eventCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns eventCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from eventCombo
            lvdData = getappdata(handles.lvd_EditCompositeObjectiveFunctionGUI,'lvdData');
            genObjFunc = getCurrentlySelectedObjFunc(app, handles);

            evtNum = get(hObject,'Value');
            event = lvdData.script.getEventForInd(evtNum);
            genObjFunc.event = event;
            genObjFunc.fcn.event = event;

            updateValueLabels(app, handles);
            
            app.listboxCallback(handles);
        end

        % Window key press function: lvd_EditCompositeObjectiveFunctionGUI
        function lvd_EditCompositeObjectiveFunctionGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditCompositeObjectiveFunctionGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditCompositeObjectiveFunctionGUI);
            end
        end

        % Value changed function: objFuncListbox
        function objFuncListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            %             hObject = handles.objFuncListbox;

            % hObject    handle to objFuncListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            app.listboxCallback(handles);

            % Hints: contents = cellstr(get(hObject,'String')) returns objFuncListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from objFuncListbox
            %             lvdData = getappdata(handles.lvd_EditCompositeObjectiveFunctionGUI,'lvdData');
            %             lvdOpt = lvdData.optimizer;
            %             compObjFcn = lvdOpt.objFcn;
            %
            %             handles.eventCombo.String = lvdData.script.getListboxStr();
            %
            %             if(isempty(compObjFcn.objFcns))
            %                 handles.removeObjFuncButton.Enable = 'off';
            %
            %                 handles.objFuncListbox.String = '<No Objective Functions>';
            %                 handles.objFuncListbox.Value = 1;
            %
            %                 handles.objFuncTypeLabel.String = 'None';
            %                 handles.eventCombo.Enable = 'off';
            %                 %         handles.refCelBodyCombo.Enable = 'off';
            %                 handles.refFrameTypeCombo.Enable = 'off';
            %                 handles.setFrameOptionsButton.Enable = 'off';
            %                 handles.refFrameNameLabel.String = '';
            %                 handles.scaleFactorText.Enable = 'off';
            %
            %                 frame = LvdData.getDefaultInitialBodyInfo(lvdData.celBodyData).getBodyCenteredInertialFrame();
            %                 setappdata(handles.lvd_EditCompositeObjectiveFunctionGUI,'frame',frame);
            %             else
            %                 handles.removeObjFuncButton.Enable = 'on';
            %                 handles.eventCombo.Enable = 'on';
            %                 handles.refFrameTypeCombo.Enable = 'on';
            %                 handles.setFrameOptionsButton.Enable = 'on';
            %                 handles.scaleFactorText.Enable = 'on';
            %
            %                 value = get(hObject,'Value');
            %                 handles.objFuncListbox.String = compObjFcn.getListBoxStr();
            %
            %                 objFun = compObjFcn.objFcns(value);
            %                 handles.objFuncTypeLabel.String = objFun.fcn.getConstraintType();
            %                 handles.objFuncTypeLabel.TooltipString = objFun.fcn.getConstraintType();
            %
            %                 eventNum = objFun.event.getEventNum();
            %                 handles.eventCombo.Value = eventNum;
            %
            %                 frame = objFun.frame;
            %                 if(isempty(frame))
            %                     frame = LvdData.getDefaultInitialBodyInfo(lvdData.celBodyData).getBodyCenteredInertialFrame();
            %                     objFun.frame = frame;
            %                 end
            %                 setappdata(handles.lvd_EditCompositeObjectiveFunctionGUI,'frame',frame);
            %
            %                 handles.refFrameTypeCombo.String = ReferenceFrameEnum.getListBoxStr();
            %                 [ind, ~] = ReferenceFrameEnum.getIndForName(frame.typeEnum.name);
            %                 handles.refFrameTypeCombo.Value = ind;
            %                 handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', frame.getNameStr());
            %                 handles.refFrameNameLabel.String = sprintf('%s', frame.getNameStr());
            %                 handles.refFrameNameLabel.TooltipString = sprintf('%s', frame.getNameStr());
            %
            %                 handles.scaleFactorText.String = fullAccNum2Str(objFun.scaleFactor);
            %             end
            %
            %             updateValueLabels(app, handles);
        end

        % Value changed function: refFrameTypeCombo
        function refFrameTypeCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to refFrameTypeCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns refFrameTypeCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from refFrameTypeCombo
            updateFrameChange(app, handles);

            genObjFunc = getCurrentlySelectedObjFunc(app, handles);
            frame = getappdata(handles.lvd_EditCompositeObjectiveFunctionGUI,'frame');
            genObjFunc.frame = frame;

            updateValueLabels(app, handles);
        end

        % Button pushed function: removeObjFuncButton
        function removeObjFuncButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeObjFuncButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditCompositeObjectiveFunctionGUI,'lvdData');
            compObjFunc = lvdData.optimizer.objFcn;

%             selObjFunc = get(handles.objFuncListbox,'Value');
%             genObjFunc = compObjFunc.getObjFuncForInd(selObjFunc);
            genObjFunc = app.objFuncListbox.Value;
            if(not(isempty(genObjFunc)))
               compObjFunc.removeObjFunc(genObjFunc);

    %             listBoxStr = compObjFunc.getListBoxStr();
    %             set(handles.objFuncListbox,'String',listBoxStr);
                    [listBoxStr, objFuncs] = compObjFunc.getListBoxStr();
                    app.objFuncListbox.Items = listBoxStr;
                    app.objFuncListbox.ItemsData = objFuncs;
    
    %             numObjFuncs = length(listBoxStr);
    %             if(selObjFunc > numObjFuncs)
    %                 set(handles.objFuncListbox,'Value',numObjFuncs);
    %             end
    
    %             objFuncListbox_Callback(app, evt);
                app.listboxCallback(handles);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %             uiresume(handles.lvd_EditCompositeObjectiveFunctionGUI);
            app.saveAndClose(handles);
        end

        % Value changed function: scaleFactorText
        function scaleFactorText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to scaleFactorText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of scaleFactorText as text
            %        str2double(get(hObject,'String')) returns contents of scaleFactorText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            genObjFunc = getCurrentlySelectedObjFunc(app, handles);

            errMsg = {};
            scaleFactor = str2double(get(handles.scaleFactorText,'String'));
            enteredStr = get(handles.scaleFactorText,'String');
            numberName = 'Scale Factor';
            lb = 1E-20;
            ub = 1E20;
            isInt = false;
            errMsg = validateNumber(scaleFactor, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                genObjFunc.scaleFactor = scaleFactor;
            else
                handles.scaleFactorText.String = fullAccNum2Str(genObjFunc.scaleFactor);
                msgbox(errMsg,'Invalid Objective Function Inputs','error');
            end

            updateValueLabels(app, handles);
        end

        % Button pushed function: setFrameOptionsButton
        function setFrameOptionsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to setFrameOptionsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            curFrame = getappdata(handles.lvd_EditCompositeObjectiveFunctionGUI,'frame');
            curFrame = curFrame.editFrameDialogUI(EditReferenceFrameContextEnum.ForState);

            genObjFunc = getCurrentlySelectedObjFunc(app, handles);
            genObjFunc.frame = curFrame;

            setappdata(handles.lvd_EditCompositeObjectiveFunctionGUI,'frame',curFrame);

            handles.setFrameOptionsButton.Tooltip = sprintf('Current Frame: %s', curFrame.getNameStr());
            handles.refFrameNameLabel.String = sprintf('%s', curFrame.getNameStr());
            handles.refFrameNameLabel.TooltipString = sprintf('%s', curFrame.getNameStr());

            updateValueLabels(app, handles);
        end

        % Window button down function: 
        % lvd_EditCompositeObjectiveFunctionGUI
        function lvd_EditCompositeObjectiveFunctionGUIWindowButtonDown(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            selType = app.lvd_EditCompositeObjectiveFunctionGUI.SelectionType;
            curObj = app.lvd_EditCompositeObjectiveFunctionGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.objFuncListbox && numel(app.objFuncListbox.Items) > 0)

                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditCompositeObjectiveFunctionGUI = uifigure('Visible', 'off');
            app.lvd_EditCompositeObjectiveFunctionGUI.Name = 'Edit Objective Function';
            app.lvd_EditCompositeObjectiveFunctionGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditCompositeObjectiveFunctionGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_EditCompositeObjectiveFunctionGUIWindowButtonDown, true);
            app.lvd_EditCompositeObjectiveFunctionGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditCompositeObjectiveFunctionGUI_WindowKeyPressFcn, true);
            app.lvd_EditCompositeObjectiveFunctionGUI.Tag = 'lvd_EditCompositeObjectiveFunctionGUI';
            app.GridLayout = uigridlayout(app.lvd_EditCompositeObjectiveFunctionGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Objective Function';
            app.saveAndCloseButton = uibutton(app.GridLayout, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout2 = uigridlayout(app.uipanel1);
            app.uipanel3 = uipanel(app.GridLayout2);
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout3 = uigridlayout(app.uipanel3);
            app.text8 = uilabel(app.GridLayout3);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Optimization Type';
            app.optDirTypeCombo = uidropdown(app.GridLayout3);
            app.optDirTypeCombo.Items = {'ENGINE NAME'};
            app.optDirTypeCombo.Tag = 'optDirTypeCombo';
            app.optDirTypeCombo.Tooltip = 'If "minimize", the code will try to make the composite of the above functions as close to negative infinity as possible.  Maximize will make the composite as close to positive infinity as possible.';
            app.optDirTypeCombo.Value = 'ENGINE NAME';
            app.text9 = uilabel(app.GridLayout3);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Composition Type';
            app.compositionTypeCombo = uidropdown(app.GridLayout3);
            app.compositionTypeCombo.Items = {'ENGINE NAME'};
            app.compositionTypeCombo.Tag = 'compositionTypeCombo';
            app.compositionTypeCombo.Tooltip = 'This dictates how the above functions are "composited" together into a single number which can be minimized or maximized.  The scaled values of the functions are the values which are composited together.';
            app.compositionTypeCombo.Value = 'ENGINE NAME';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.addObjFuncButton = uibutton(app.GridLayout6, 'push');
            app.addObjFuncButton.ButtonPushedFcn = createCallbackFcn(app, @addObjFuncButton_Callback, true);
            app.addObjFuncButton.Tag = 'addObjFuncButton';
            app.addObjFuncButton.Icon = 'add.png';
            app.addObjFuncButton.Tooltip = 'Add Objective Function';
            app.addObjFuncButton.Text = 'Add Obj. Function';
            app.removeObjFuncButton = uibutton(app.GridLayout6, 'push');
            app.removeObjFuncButton.ButtonPushedFcn = createCallbackFcn(app, @removeObjFuncButton_Callback, true);
            app.removeObjFuncButton.Tag = 'removeObjFuncButton';
            app.removeObjFuncButton.Icon = 'remove.png';
            app.removeObjFuncButton.Tooltip = 'Remove Objective Function';
            app.removeObjFuncButton.Text = 'Remove Obj. Function';
            app.objFuncListbox = uilistbox(app.GridLayout5);
            app.objFuncListbox.Items = {'Listbox'};
            app.objFuncListbox.ValueChangedFcn = createCallbackFcn(app, @objFuncListbox_Callback, true);
            app.objFuncListbox.Tag = 'objFuncListbox';
            app.objFuncListbox.Tooltip = 'List of all objective functions.  If empty, the objective function passed to the optimizer will return a constant value.';
            app.objFuncListbox.Value = 'Listbox';
            app.uipanel2 = uipanel(app.GridLayout4);
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout7 = uigridlayout(app.uipanel2);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.text10 = uilabel(app.GridLayout8);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Type';
            app.objFuncTypeLabel = uilabel(app.GridLayout8);
            app.objFuncTypeLabel.Tag = 'objFuncTypeLabel';
            app.objFuncTypeLabel.Text = 'Obj Func Type';
            app.GridLayout9 = uigridlayout(app.GridLayout7);
            app.text3 = uilabel(app.GridLayout9);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Evaluate at Event';
            app.eventCombo = uidropdown(app.GridLayout9);
            app.eventCombo.Items = {'ENGINE NAME'};
            app.eventCombo.ValueChangedFcn = createCallbackFcn(app, @eventCombo_Callback, true);
            app.eventCombo.Tag = 'eventCombo';
            app.eventCombo.Tooltip = 'This function will be evaluated at the end of the event selected here.';
            app.eventCombo.Value = 'ENGINE NAME';
            app.GridLayout10 = uigridlayout(app.GridLayout7);
            app.text5 = uilabel(app.GridLayout10);
            app.text5.Tag = 'text5';
            app.text5.Tooltip = 'This number divides the value of the constraint.  Set such that the typical value of this constraint is 1.0.  Set to 1.0 if you''re unsure of what to put here.';
            app.text5.Text = 'Scale Factor';
            app.scaleFactorText = uieditfield(app.GridLayout10, 'text');
            app.scaleFactorText.ValueChangedFcn = createCallbackFcn(app, @scaleFactorText_Callback, true);
            app.scaleFactorText.Tag = 'scaleFactorText';
            app.scaleFactorText.Value = '1.0';
            app.GridLayout11 = uigridlayout(app.GridLayout7);
            app.text12 = uilabel(app.GridLayout11);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Current Value';
            app.curValueLabel = uilabel(app.GridLayout11);
            app.curValueLabel.Tag = 'curValueLabel';
            app.curValueLabel.Tooltip = 'Current value of this objective function based on the last execution of the script.';
            app.curValueLabel.Text = 'Obj Func Type';
            app.curValUnitLabel = uilabel(app.GridLayout11);
            app.curValUnitLabel.Tag = 'curValUnitLabel';
            app.curValUnitLabel.Text = 'unit';
            app.GridLayout12 = uigridlayout(app.GridLayout7);
            app.text14 = uilabel(app.GridLayout12);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Cur. Scaled Value';
            app.curValScaledLabel = uilabel(app.GridLayout12);
            app.curValScaledLabel.Tag = 'curValScaledLabel';
            app.curValScaledLabel.Tooltip = 'Current value of this objective function based on the last execution of the script, and then scaled by the scale factor.';
            app.curValScaledLabel.Text = 'Obj Func Type';
            app.curValScaledUnitLabel = uilabel(app.GridLayout12);
            app.curValScaledUnitLabel.Tag = 'curValScaledUnitLabel';
            app.curValScaledUnitLabel.Text = '';
            app.uipanel4 = uipanel(app.GridLayout7);
            app.uipanel4.Title = 'Reference Frame';
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout13 = uigridlayout(app.uipanel4);
            app.refFrameNameLabel = uilabel(app.GridLayout13);
            app.refFrameNameLabel.Tag = 'refFrameNameLabel';
            app.refFrameNameLabel.Text = 'Static Text';
            app.GridLayout14 = uigridlayout(app.GridLayout13);
            app.text19 = uilabel(app.GridLayout14);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Frame Type';
            app.refFrameTypeCombo = uidropdown(app.GridLayout14);
            app.refFrameTypeCombo.Items = {};
            app.refFrameTypeCombo.ValueChangedFcn = createCallbackFcn(app, @refFrameTypeCombo_Callback, true);
            app.refFrameTypeCombo.Tag = 'refFrameTypeCombo';
            app.refFrameTypeCombo.Tooltip = 'This is the reference frame in which the selected quantity will be evaluated in.';
            app.refFrameTypeCombo.Value = {};
            app.GridLayout15 = uigridlayout(app.GridLayout13);
            app.setFrameOptionsButton = uibutton(app.GridLayout15, 'push');
            app.setFrameOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @setFrameOptionsButton_Callback, true);
            app.setFrameOptionsButton.Tag = 'setFrameOptionsButton';
            app.setFrameOptionsButton.Icon = 'reference_frame.png';
            app.setFrameOptionsButton.Tooltip = 'Sets parameters for the selected reference frame type.';
            app.setFrameOptionsButton.Text = 'Set Frame Options';
            % Show the figure after all components are created
            app.lvd_EditCompositeObjectiveFunctionGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditCompositeObjectiveFunctionGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditCompositeObjectiveFunctionGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditCompositeObjectiveFunctionGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditCompositeObjectiveFunctionGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditCompositeObjectiveFunctionGUI)
        end
    end
end