classdef lvd_EditConstraintsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditConstraintsGUI  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        closeButton             matlab.ui.control.Button
        GridLayout2             matlab.ui.container.GridLayout
        Panel_2                 matlab.ui.container.Panel
        GridLayout5             matlab.ui.container.GridLayout
        ConstraintInfoTextArea  matlab.ui.control.TextArea
        Panel                   matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        GridLayout4             matlab.ui.container.GridLayout
        removeConstraintButton  matlab.ui.control.Button
        addConstraintButton     matlab.ui.control.Button
        constraintsListBox      matlab.ui.control.ListBox
        constraintSearchText      matlab.ui.control.EditField
        ConstraintTagsGrid        matlab.ui.container.GridLayout
        constraintTagsText        matlab.ui.control.EditField
        applyConstraintTagsButton matlab.ui.control.Button
        text2                   matlab.ui.control.Label
    end

    properties (Access = private)
        lvdData LvdData
    end

    methods (Access = private)
        function mouseMovedCallback(app, jListbox, jEventData, hListbox, tooltipStrs)
%             % Get the currently-hovered list-item
%             mousePos = java.awt.Point(jEventData.getX, jEventData.getY);
%             hoverIndex = jListbox.locationToIndex(mousePos) + 1;
% 
%             % Modify the tooltip based on the hovered item
%             if(hoverIndex > 0 && hoverIndex <= length(tooltipStrs))
%                 msgStr = tooltipStrs{hoverIndex};
%                 set(hListbox, 'Tooltip',msgStr);
%             else
%                 set(hListbox, 'Tooltip','');
%             end 
        end

        function populateGUI(app, handles)
            app.constraintSearchText.Value = '';
            [~, ~] = app.setListboxStr('');
        end
        
        function [listboxStr, consts] = setListboxStr(app, query)
            if(nargin < 2)
                query = app.constraintSearchText.Value;
            end
            
            hFig = app.lvd_EditConstraintsGUI;
            currentValue = app.constraintsListBox.Value;
            hadSelection = isa(currentValue, 'AbstractConstraint');
            if(hadSelection)
                setappdata(hFig, 'h8Selected', currentValue);
            end
            lvdData = getappdata(app.lvd_EditConstraintsGUI,'lvdData');
            [listboxStr, consts] = app.lvdData.optimizer.constraints.getFilteredListboxStr(query);
            storedValue = [];
            if(isappdata(hFig, 'h8Selected'))
                storedValue = getappdata(hFig, 'h8Selected');
            end
            selectedValue = lvd_selectVisibleListValue({currentValue, storedValue}, consts);
            app.constraintsListBox.ItemsData = consts;
            app.constraintsListBox.Items = listboxStr;
            if(not(isempty(selectedValue)))
                app.constraintsListBox.Value = selectedValue;
                setappdata(hFig, 'h8Selected', selectedValue);
            elseif(hadSelection)
                %A visible selection that was filtered out stays cleared.
            elseif(not(isempty(consts)))
                app.constraintsListBox.Value = consts(1);
                setappdata(hFig, 'h8Selected', consts(1));
            end
            
            num = numel(consts);
            if(num <= 0)
                app.removeConstraintButton.Enable = 'off';
            else
                app.removeConstraintButton.Enable = 'on';
            end
            setTooltipStrings(app, []);
            app.updateConstraintTagsField();
        end

        function setTooltipStrings(app, handles)
            [~, consts, tooltipStrs] = app.lvdData.optimizer.constraints.getFilteredListboxStr(app.constraintSearchText.Value);
            
            const = app.constraintsListBox.Value;
            if(numel(consts) > 0 && not(isempty(const)))
                ind = find(const == consts, 1, 'first');
                tooltipStr = tooltipStrs(ind);
                app.ConstraintInfoTextArea.Value = tooltipStr;
            else
                app.ConstraintInfoTextArea.Value = '';
            end
        end
 
        function updateConstraintTagsField(app)
            const = app.constraintsListBox.Value;
            if(isempty(const))
                app.constraintTagsText.Value = '';
                app.applyConstraintTagsButton.Enable = 'off';
            else
                app.constraintTagsText.Value = const.tags;
                app.applyConstraintTagsButton.Enable = 'on';
            end
        end
 
        function applyConstraintTags(app)
            const = app.constraintsListBox.Value;
            if(isempty(const))
                return;
            end
 
            const.tags = strtrim(app.constraintTagsText.Value);
            [~, ~] = app.setListboxStr();
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditConstraintsGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditConstraintsGUI (see VARARGIN)

            % Choose default command line output for lvd_EditConstraintsGUI
            handles.output = hObject;

            centerUIFigure(app.lvd_EditConstraintsGUI);
            applySelectedThemeToApp(app);
            
            app.lvdData = varargin{1};
            % setappdata(app.lvd_EditConstraintsGUI,'lvdData',lvdData);

            populateGUI(app, handles);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditConstraintsGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditConstraintsGUI);
        end

        % Button pushed function: addConstraintButton
        function addConstraintButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
 
            % hObject    handle to addConstraintButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % lvdData = getappdata(handles.lvd_EditConstraintsGUI,'lvdData');
 
            listBoxStr = ConstraintEnum.getListBoxStr();
 
            out = AppDesignerGUIOutput();
            listdlgARH_App('ListString',listBoxStr, ...
                'SelectionMode', 'single', ...
                'ListSize', [300 300], ...
                'Name', 'Constraint Type', ...
                'PromptString', 'Select the constraint type:', ...
                'out',out);
            Selection = out.output{1};
            ok = out.output{2};
 
            if(ok == 1)
                [~, enum] = ConstraintEnum.getIndForName(listBoxStr{Selection});
                constClass = enum.class;
                input1 = enum.constructorInput1; %#ok<NASGU> 
 
                newConstraint = eval(sprintf('%s.getDefaultConstraint(%s, %s)', constClass, 'input1', 'app.lvdData'));
 
                addConstraintTf = newConstraint.openEditConstraintUI(app.lvdData);
 
                if(addConstraintTf)
                    constraintSet = app.lvdData.optimizer.constraints;
                    constraintSet.addConstraint(newConstraint);
                    app.constraintSearchText.Value = '';
                    [~, ~] = app.setListboxStr('');
                    app.constraintsListBox.Value = newConstraint;
                    app.updateConstraintTagsField();
                    setTooltipStrings(app, handles);
                end
            end
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to closeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditConstraintsGUI);
        end

        % Value changed function: constraintsListBox
        function constraintsListBox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
 
            % Hints: contents = cellstr(get(hObject,'String')) returns constraintsListBox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from constraintsListBox
            setTooltipStrings(app, handles)
            app.updateConstraintTagsField();
        end

        % Window key press function: lvd_EditConstraintsGUI
        function lvd_EditConstraintsGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
 
            % hObject    handle to figure
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            switch(eventdata.Key)
                case 'return'
                    if(app.lvd_EditConstraintsGUI.CurrentObject == app.constraintSearchText)
                        return;
                    end
                    if(app.lvd_EditConstraintsGUI.CurrentObject == app.constraintTagsText)
                        return;
                    end
                    close(handles.lvd_EditConstraintsGUI);
                case 'enter'
                    if(app.lvd_EditConstraintsGUI.CurrentObject == app.constraintSearchText)
                        return;
                    end
                    if(app.lvd_EditConstraintsGUI.CurrentObject == app.constraintTagsText)
                        return;
                    end
                    close(handles.lvd_EditConstraintsGUI);
                case 'escape'
                    close(handles.lvd_EditConstraintsGUI);
            end
        end

        % Button pushed function: removeConstraintButton
        function removeConstraintButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
 
            % hObject    handle to removeConstraintButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % lvdData = getappdata(handles.lvd_EditConstraintsGUI,'lvdData');
            constraintSet = app.lvdData.optimizer.constraints;
 
            constraint = app.constraintsListBox.Value;
            oldInd = app.constraintsListBox.ValueIndex;
 
            constraintSet.removeConstraint(constraint);
 
            [~, visibleConsts] = app.setListboxStr(app.constraintSearchText.Value);
 
            if(not(isempty(visibleConsts)))
                app.constraintsListBox.Value = visibleConsts(min(oldInd, numel(visibleConsts)));
                setTooltipStrings(app, handles);
                app.updateConstraintTagsField();
            end
        end

        % Window button down function: lvd_EditConstraintsGUI
        function lvd_EditConstraintsGUIWindowButtonDown(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            selType = app.lvd_EditConstraintsGUI.SelectionType;
            curObj = app.lvd_EditConstraintsGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.constraintsListBox && numel(app.constraintsListBox.ItemsData) > 0)   
                    constraint = app.constraintsListBox.Value;
                    constraint.openEditConstraintUI(app.lvdData);
    
                    [~, ~] = app.setListboxStr();
    
                    setTooltipStrings(app, handles);
                end
            end
        end

        % Window button motion function: lvd_EditConstraintsGUI
        function lvd_EditConstraintsGUIWindowButtonMotion(app, event)

        end

 
        % Value changing function: constraintSearchText
        function constraintSearchTextValueChanging(app, event)
            [~, ~] = app.setListboxStr(event.Value);
        end
        % Value changed function: constraintSearchText
        function constraintSearchTextValueChanged(app, event)
            [~, ~] = app.setListboxStr(event.Value);
        end
        % Value changed function: constraintTagsText
        function constraintTagsTextValueChanged(app, event)
            app.applyConstraintTags();
        end
        % Button pushed function: applyConstraintTagsButton
        function applyConstraintTagsButton_Callback(app, event)
            app.applyConstraintTags();
        end
    end
    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditConstraintsGUI = uifigure('Visible', 'off');
            app.lvd_EditConstraintsGUI.Name = 'Edit Constraints';
            app.lvd_EditConstraintsGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditConstraintsGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_EditConstraintsGUIWindowButtonDown, true);
            app.lvd_EditConstraintsGUI.WindowButtonMotionFcn = createCallbackFcn(app, @lvd_EditConstraintsGUIWindowButtonMotion, true);
            app.lvd_EditConstraintsGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditConstraintsGUI_WindowKeyPressFcn, true);
            app.lvd_EditConstraintsGUI.Tag = 'lvd_EditConstraintsGUI';
            app.GridLayout = uigridlayout(app.lvd_EditConstraintsGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Constraints';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Panel = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.constraintSearchText = uieditfield(app.GridLayout3, 'text');
            app.constraintSearchText.ValueChangingFcn = createCallbackFcn(app, @constraintSearchTextValueChanging, true);
            app.constraintSearchText.ValueChangedFcn = createCallbackFcn(app, @constraintSearchTextValueChanged, true);
            app.constraintSearchText.Tag = 'constraintSearchText';
            app.constraintSearchText.Tooltip = 'Type to filter the constraints below.';
            app.constraintSearchText.Placeholder = 'Search constraints...';
            app.constraintsListBox = uilistbox(app.GridLayout3);
            app.constraintsListBox.Items = {'Listbox'};
            app.constraintsListBox.ValueChangedFcn = createCallbackFcn(app, @constraintsListBox_Callback, true);
            app.constraintsListBox.Tag = 'constraintsListBox';
            app.constraintsListBox.Value = 'Listbox';
            app.ConstraintTagsGrid = uigridlayout(app.GridLayout3);
            app.constraintTagsText = uieditfield(app.ConstraintTagsGrid, 'text');
            app.constraintTagsText.ValueChangedFcn = createCallbackFcn(app, @constraintTagsTextValueChanged, true);
            app.constraintTagsText.Tag = 'constraintTagsText';
            app.constraintTagsText.Tooltip = 'Free-text tags for the selected constraint. The search box matches these tags.';
            app.constraintTagsText.Placeholder = 'Tags for selected constraint...';
            app.applyConstraintTagsButton = uibutton(app.ConstraintTagsGrid, 'push');
            app.applyConstraintTagsButton.ButtonPushedFcn = createCallbackFcn(app, @applyConstraintTagsButton_Callback, true);
            app.applyConstraintTagsButton.Tag = 'applyConstraintTagsButton';
            app.applyConstraintTagsButton.Text = 'Apply Tags';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.addConstraintButton = uibutton(app.GridLayout4, 'push');
            app.addConstraintButton.ButtonPushedFcn = createCallbackFcn(app, @addConstraintButton_Callback, true);
            app.addConstraintButton.Tag = 'addConstraintButton';
            app.addConstraintButton.Icon = 'add.png';
            app.addConstraintButton.Text = 'Add Constraint';
            app.removeConstraintButton = uibutton(app.GridLayout4, 'push');
            app.removeConstraintButton.ButtonPushedFcn = createCallbackFcn(app, @removeConstraintButton_Callback, true);
            app.removeConstraintButton.Tag = 'removeConstraintButton';
            app.removeConstraintButton.Icon = 'remove.png';
            app.removeConstraintButton.Text = 'Remove Constraint';
            app.Panel_2 = uipanel(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.Panel_2);
            app.ConstraintInfoTextArea = uitextarea(app.GridLayout5);
            app.ConstraintInfoTextArea.Editable = 'off';
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Icon = 'close_window(1).png';
            app.closeButton.Text = 'Close';
            % Show the figure after all components are created
            app.lvd_EditConstraintsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditConstraintsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditConstraintsGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditConstraintsGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditConstraintsGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditConstraintsGUI)
        end
    end
end