classdef lvd_EditGeometricAngleConstraintGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditGeometricAngleConstraintGUI  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        uipanel1                  matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        uipanel3                  matlab.ui.container.Panel
        GridLayout10              matlab.ui.container.GridLayout
        comparisonTypeCombo       matlab.ui.control.DropDown
        stateComparisonNodeCombo  matlab.ui.control.DropDown
        compEventCombo            matlab.ui.control.DropDown
        text21                    matlab.ui.control.Label
        text24                    matlab.ui.control.Label
        text20                    matlab.ui.control.Label
        uipanel2                  matlab.ui.container.Panel
        GridLayout9               matlab.ui.container.GridLayout
        lbUnitLabel               matlab.ui.control.Label
        ubUnitLabel               matlab.ui.control.Label
        lbText                    matlab.ui.control.EditField
        ubText                    matlab.ui.control.EditField
        text5                     matlab.ui.control.Label
        text13                    matlab.ui.control.Label
        uipanel4                  matlab.ui.container.Panel
        GridLayout6               matlab.ui.container.GridLayout
        GridLayout8               matlab.ui.container.GridLayout
        setFrameOptionsButton     matlab.ui.control.Button
        GridLayout7               matlab.ui.container.GridLayout
        refFrameTypeCombo         matlab.ui.control.DropDown
        text22                    matlab.ui.control.Label
        refFrameNameLabel         matlab.ui.control.Label
        constActiveCheckbox       matlab.ui.control.CheckBox
        useAbsValueCheckbox       matlab.ui.control.CheckBox
        GridLayout5               matlab.ui.container.GridLayout
        constraintEvalTypeCombo   matlab.ui.control.DropDown
        text19                    matlab.ui.control.Label
        GridLayout4               matlab.ui.container.GridLayout
        scaleFactorText           matlab.ui.control.EditField
        angleCombo                matlab.ui.control.DropDown
        eventNodeCombo            matlab.ui.control.DropDown
        eventCombo                matlab.ui.control.DropDown
        constraintTypeLabel       matlab.ui.control.Label
        text18                    matlab.ui.control.Label
        text15                    matlab.ui.control.Label
        text25                    matlab.ui.control.Label
        text17                    matlab.ui.control.Label
        stageName                 matlab.ui.control.Label
        GridLayout2               matlab.ui.container.GridLayout
        cancelButton              matlab.ui.control.Button
        saveAndCloseButton        matlab.ui.control.Button
        text2                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput
    end
    
    methods (Access = private)
        function bodyInfo = getSelectedBodyInfo(app, handles)
                curFrame = getappdata(handles.lvd_EditGeometricAngleConstraintGUI,'frame');
                bodyInfo = curFrame.getOriginBody();
        end
        
        function populateGUI(app, handles, constraint, lvdData)
                handles.constraintTypeLabel.String = constraint.getConstraintType();
                handles.constraintTypeLabel.TooltipString = constraint.getConstraintType();
                [unit, ~, ~, usesLbUb, ~, ~] = constraint.getConstraintStaticDetails();
            
                if(usesLbUb)
                    [lb, ub] = constraint.getBounds();
                    handles.ubText.String = fullAccNum2Str(ub);
                    handles.lbText.String = fullAccNum2Str(lb);
                else
                    handles.ubText.String = fullAccNum2Str(0);
                    handles.lbText.String = fullAccNum2Str(0);
                    handles.lbText.Enable = 'off';
                    handles.ubText.Enable = 'off';
                end
            
                handles.scaleFactorText.String = fullAccNum2Str(constraint.getScaleFactor());
            
                handles.ubUnitLabel.String = unit;
                handles.lbUnitLabel.String = unit;
            
                evtNum = lvdData.script.getNumOfEvent(constraint.event);
                handles.eventCombo.String = lvdData.script.getListboxStr();
                if(not(isempty(evtNum)))
                    handles.eventCombo.Value = evtNum;
                elseif(~isempty(handles.eventCombo.String))
                    handles.eventCombo.Value = 1;
                end
            
                handles.angleCombo.String = lvdData.geometry.angles.getListboxStr();
            
                if(isempty(constraint.angle))
                    angleInd = 1;
                else
                    angleInd = lvdData.geometry.angles.getIndsForAngles(constraint.angle);
                end
                if(~isempty(handles.angleCombo.String))
                    handles.angleCombo.Value = angleInd;
                end
            
                frame = constraint.frame;
                if(isempty(frame))
                    frame = LvdData.getDefaultInitialBodyInfo(lvdData.celBodyData).getBodyCenteredInertialFrame();
                    constraint.frame = frame;
                end
                setappdata(handles.lvd_EditGeometricAngleConstraintGUI,'frame',frame);
            
                handles.refFrameTypeCombo.String = ReferenceFrameEnum.getListBoxStr();
                [ind, ~] = ReferenceFrameEnum.getIndForName(frame.typeEnum.name);
                handles.refFrameTypeCombo.Value = ind;
                handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', frame.getNameStr());
                handles.refFrameNameLabel.String = sprintf('%s', frame.getNameStr());
                handles.refFrameNameLabel.TooltipString = sprintf('%s', frame.getNameStr());
            
                handles.constraintEvalTypeCombo.String = ConstraintEvalTypeEnum.getListBoxStr();
                handles.constraintEvalTypeCombo.Value = ConstraintEvalTypeEnum.getIndForName(constraint.evalType.name);
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                constraintEvalTypeCombo_Callback(app, evt);
            
                evtNum = lvdData.script.getNumOfEvent(constraint.stateCompEvent);
                handles.compEventCombo.String = lvdData.script.getListboxStr();
                if(not(isempty(evtNum)))
                    handles.compEventCombo.Value = evtNum;
                elseif(~isempty(handles.compEventCombo.String))
                    handles.compEventCombo.Value = 1;
                end
            
                handles.eventNodeCombo.String = ConstraintStateComparisonNodeEnum.getListBoxStr();
                handles.eventNodeCombo.Value = ConstraintStateComparisonNodeEnum.getIndForName(constraint.eventNode.name);
            
                handles.stateComparisonNodeCombo.String = ConstraintStateComparisonNodeEnum.getListBoxStr();
                handles.stateComparisonNodeCombo.Value = ConstraintStateComparisonNodeEnum.getIndForName(constraint.stateCompNode.name);
            
                handles.comparisonTypeCombo.String = ConstraintStateComparisonTypeEnum.getListBoxStr();
                handles.comparisonTypeCombo.Value = ConstraintStateComparisonTypeEnum.getIndForName(constraint.stateCompType.name);
            
                handles.useAbsValueCheckbox.Value = double(constraint.useAbsValue);
                handles.constActiveCheckbox.Value = constraint.active;
        end
        
        function updateFrameChange(app, handles)
                lvdData = getappdata(handles.lvd_EditGeometricAngleConstraintGUI,'lvdData');
                celBodyData = lvdData.celBodyData;
            
                refEnumListBoxStr = ReferenceFrameEnum.getListBoxStr();
                selFrameTypeInd = handles.refFrameTypeCombo.Value;
                refFrameEnum = ReferenceFrameEnum.getEnumForListboxStr(refEnumListBoxStr{selFrameTypeInd});
            
            %             if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
            %                 refFrameEnum = newFrame.typeEnum;
            %                 app.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
            %             end
            
                switch refFrameEnum
                    case ReferenceFrameEnum.BodyCenteredInertial
                        bodyInfo = getSelectedBodyInfo(app, handles);
            
                        newFrame = bodyInfo.getBodyCenteredInertialFrame();
            
                    case ReferenceFrameEnum.BodyFixedRotating
                        bodyInfo = getSelectedBodyInfo(app, handles);
            
                        newFrame = bodyInfo.getBodyFixedFrame();
            
                    case ReferenceFrameEnum.TwoBodyRotating
                        bodyInfo = getSelectedBodyInfo(app, handles);
                        if(not(isempty(bodyInfo.childrenBodyInfo)))
                            primaryBody = bodyInfo;
                            secondaryBody = bodyInfo.childrenBodyInfo(1);
                        else
                            primaryBody = bodyInfo.getParBodyInfo();
                            secondaryBody = bodyInfo;
                        end
            
                        originPt = TwoBodyRotatingFrameOriginEnum.Primary;
            
                        newFrame = TwoBodyRotatingFrame(primaryBody, secondaryBody, originPt, celBodyData);
            
                    case ReferenceFrameEnum.UserDefined
                        numFrames = lvdData.geometry.refFrames.getNumRefFrames();
                        if(numFrames >= 1)
                            geometricFrame = AbstractGeometricRefFrame.empty(1,0);
                            for(i=1:numFrames) %#ok<*NO4LP> 
                                frame = lvdData.geometry.refFrames.getRefFrameAtInd(i);
                                if(frame.isVehDependent() == false)
                                    geometricFrame = frame;
                                    break;
                                end
                            end
            
                            if(not(isempty(geometricFrame)))
                                newFrame = UserDefinedGeometricFrame(geometricFrame, lvdData);
                            else
                                bodyInfo = getSelectedBodyInfo(app, handles);
                                newFrame = bodyInfo.getBodyCenteredInertialFrame();
            
                                warndlg('There are no geometric frames available which are not dependent on vehicle properties.  A body-centered inertial frame will be selected instead.');
                            end
                        else
                            bodyInfo = getSelectedBodyInfo(app, handles);
                            newFrame = bodyInfo.getBodyCenteredInertialFrame();
            
                            warndlg('There are no geometric frames available which are not dependent on vehicle properties.  A body-centered inertial frame will be selected instead.');
                        end
            
                    otherwise
                        error('Unknown reference frame type: %s', string(refFrameEnum));
                end
            
                if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
                    refFrameEnum = newFrame.typeEnum;
                    handles.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
                end
            
                setappdata(handles.lvd_EditGeometricAngleConstraintGUI,'frame', newFrame);
                handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', newFrame.getNameStr());
                handles.refFrameNameLabel.String = sprintf('%s', newFrame.getNameStr());
                handles.refFrameNameLabel.TooltipString = sprintf('%s', newFrame.getNameStr());
        end
        
        function errMsg = validateInputs(app, handles)
                errMsg = {};
            
                constraint = getappdata(handles.lvd_EditGeometricAngleConstraintGUI, 'constraint');
                [~, lbLim, ubLim, usesLbUb, ~, ~] = constraint.getConstraintStaticDetails();
            
                if(usesLbUb)
                    lwrBnd = str2double(get(handles.lbText,'String'));
                    enteredStr = get(handles.lbText,'String');
                    numberName = 'Lower Bound';
                    lb = lbLim;
                    ub = Inf;
                    isInt = false;
                    errMsg = validateNumber(lwrBnd, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                    uprBnd = str2double(get(handles.ubText,'String'));
                    enteredStr = get(handles.ubText,'String');
                    numberName = 'Upper Bound';
                    lb = -Inf;
                    ub = ubLim;
                    isInt = false;
                    errMsg = validateNumber(uprBnd, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                    if(isempty(errMsg))
                        uprBnd = str2double(get(handles.ubText,'String'));
                        enteredStr = get(handles.ubText,'String');
                        numberName = 'Upper Bound';
                        lb = lwrBnd;
                        ub = ubLim;
                        isInt = false;
                        errMsg = validateNumber(uprBnd, numberName, lb, ub, isInt, errMsg, enteredStr);
                    end
                end
            
                sf = str2double(get(handles.scaleFactorText,'String'));
                enteredStr = get(handles.scaleFactorText,'String');
                numberName = 'Scale Factor';
                lb = 1E-12;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(sf, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                [~, enums] = ConstraintEvalTypeEnum.getListBoxStr();
                evalTypeEnum = enums(handles.constraintEvalTypeCombo.Value);
                if(evalTypeEnum == ConstraintEvalTypeEnum.StateComparison && ...
                   handles.eventCombo.Value == handles.compEventCombo.Value && ...
                   handles.eventNodeCombo.Value == handles.stateComparisonNodeCombo.Value)
                    errMsg{end+1} = 'When using the state comparison evaluation type, the Applicable Event and Node and Comparison Event and Node must be different.';
                end
        end
        
        
        function saveAndClose(app, handles)
        constraint = getappdata(handles.lvd_EditGeometricAngleConstraintGUI, 'constraint');
        lvdData = getappdata(handles.lvd_EditGeometricAngleConstraintGUI,'lvdData');
        
        constraint.lb = str2double(handles.lbText.String);
        constraint.ub = str2double(handles.ubText.String);
        
        constraint.setScaleFactor(str2double(handles.scaleFactorText.String));
        
        constraint.angle = lvdData.geometry.angles.getAngleAtInd(handles.angleCombo.Value);
        
        frame = getappdata(handles.lvd_EditGeometricAngleConstraintGUI,'frame');
        constraint.frame = frame;
        
        [~, enums] = ConstraintEvalTypeEnum.getListBoxStr();
        constraint.evalType = enums(handles.constraintEvalTypeCombo.Value);
        
        [~, enums] = ConstraintStateComparisonTypeEnum.getListBoxStr();
        constraint.stateCompType = enums(handles.comparisonTypeCombo.Value);
        
        [~, enums] = ConstraintStateComparisonNodeEnum.getListBoxStr();
        constraint.eventNode = enums(handles.eventNodeCombo.Value);
        
        [~, enums] = ConstraintStateComparisonNodeEnum.getListBoxStr();
        constraint.stateCompNode = enums(handles.stateComparisonNodeCombo.Value);
        
        numEvents = lvdData.script.getTotalNumOfEvents();
        if(numEvents > 0)
            eventNum = handles.eventCombo.Value;
            constraint.event = lvdData.script.getEventForInd(eventNum);
            
            if(constraint.evalType == ConstraintEvalTypeEnum.StateComparison)
                eventNum = handles.compEventCombo.Value;
                constraint.stateCompEvent = lvdData.script.getEventForInd(eventNum);
            else
                constraint.stateCompEvent = LaunchVehicleEvent.empty(1,0);
            end
        else
            constraint.event = LaunchVehicleEvent.empty(1,0);
            constraint.stateCompEvent = LaunchVehicleEvent.empty(1,0);
        end
        
        constraint.active = logical(handles.constActiveCheckbox.Value);
        constraint.useAbsValue = logical(handles.useAbsValueCheckbox.Value);
        
        app.output.output = {true};
        close(handles.lvd_EditGeometricAngleConstraintGUI);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditGeometricAngleConstraintGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
                % This function has no output args, see OutputFcn.
                % hObject    handle to figure
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
                % varargin   command line arguments to lvd_EditGeometricAngleConstraintGUI (see VARARGIN)
            
                % Choose default command line output for lvd_EditGeometricAngleConstraintGUI
                handles.output = hObject;
            
                centerUIFigure(hObject);
                applySelectedThemeToApp(app);
            
                constraint = varargin{1};
                setappdata(hObject, 'constraint', constraint);
            
                lvdData = varargin{2};
                setappdata(hObject,'lvdData',lvdData);
                
                app.output = varargin{3};
            
            	populateGUI(app, handles, constraint, lvdData);
            
                % Update handles structure
                guidata(hObject, handles);
            
                % UIWAIT makes lvd_EditGeometricAngleConstraintGUI wait for user response (see UIRESUME)
                uiwait(handles.lvd_EditGeometricAngleConstraintGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                close(handles.lvd_EditGeometricAngleConstraintGUI);
        end

        % Value changed function: constraintEvalTypeCombo
        function constraintEvalTypeCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.constraintEvalTypeCombo;
            
            % hObject    handle to constraintEvalTypeCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns constraintEvalTypeCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from constraintEvalTypeCombo
                contents = cellstr(get(hObject,'String'));
                name = contents{get(hObject,'Value')};
            
                enum = ConstraintEvalTypeEnum.getEnumForListboxStr(name);
                switch enum
                    case ConstraintEvalTypeEnum.FixedBounds
                        handles.ubText.Enable = 'on';
                        handles.lbText.Enable = 'on';
                        handles.compEventCombo.Enable = 'off';
                        handles.comparisonTypeCombo.Enable = 'off';
                        handles.stateComparisonNodeCombo.Enable = 'off';
            
                    case ConstraintEvalTypeEnum.StateComparison
                        handles.ubText.Enable = 'off';
                        handles.lbText.Enable = 'off';
                        handles.compEventCombo.Enable = 'on';
                        handles.comparisonTypeCombo.Enable = 'on';
                        handles.stateComparisonNodeCombo.Enable = 'on';
            
                    otherwise
                        error('Unknown constraint evaluation type.');
                end
        end

        % Value changed function: lbText
        function lbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to lbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of lbText as text
            %        str2double(get(hObject,'String')) returns contents of lbText as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
        end

        % Window key press function: lvd_EditGeometricAngleConstraintGUI
        function lvd_EditGeometricAngleConstraintGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to lvd_EditGeometricAngleConstraintGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
                switch(eventdata.Key)
                    case 'return'
                        saveAndCloseButton_Callback(app, evt);
                    case 'enter'
                        saveAndCloseButton_Callback(app, evt);
                    case 'escape'
                        close(handles.lvd_EditGeometricAngleConstraintGUI);
                end
        end

        % Value changed function: refFrameTypeCombo
        function refFrameTypeCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to refFrameTypeCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns refFrameTypeCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from refFrameTypeCombo
                updateFrameChange(app, handles);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                errMsg = validateInputs(app, handles);
            
                if(isempty(errMsg))
%                     uiresume(handles.lvd_EditGeometricAngleConstraintGUI);
                    app.saveAndClose(handles);
                else
%                     msgbox(errMsg,'Invalid Constraint Inputs','error');
                    uialert(app.lvd_EditGeometricAngleConstraintGUI, errMsg, 'Invalid Constraint Inputs', "Icon","error");
                end
        end

        % Value changed function: scaleFactorText
        function scaleFactorText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to scaleFactorText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of scaleFactorText as text
            %        str2double(get(hObject,'String')) returns contents of scaleFactorText as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
        end

        % Button pushed function: setFrameOptionsButton
        function setFrameOptionsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to setFrameOptionsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                curFrame = getappdata(handles.lvd_EditGeometricAngleConstraintGUI,'frame');
                curFrame = curFrame.editFrameDialogUI(EditReferenceFrameContextEnum.ForState);
            
                setappdata(handles.lvd_EditGeometricAngleConstraintGUI,'frame',curFrame);
            
                handles.setFrameOptionsButton.Tooltip = sprintf('Current Frame: %s', curFrame.getNameStr());
                handles.refFrameNameLabel.String = sprintf('%s', curFrame.getNameStr());
                handles.refFrameNameLabel.TooltipString = sprintf('%s', curFrame.getNameStr());
        end

        % Value changed function: ubText
        function ubText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ubText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ubText as text
            %        str2double(get(hObject,'String')) returns contents of ubText as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditGeometricAngleConstraintGUI = uifigure('Visible', 'off');
            app.lvd_EditGeometricAngleConstraintGUI.Name = 'Edit Constraint';
            app.lvd_EditGeometricAngleConstraintGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditGeometricAngleConstraintGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditGeometricAngleConstraintGUI_WindowKeyPressFcn, true);
            app.lvd_EditGeometricAngleConstraintGUI.Tag = 'lvd_EditGeometricAngleConstraintGUI';
            app.GridLayout = uigridlayout(app.lvd_EditGeometricAngleConstraintGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Constraint';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.stageName = uilabel(app.GridLayout4);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Constraint Type';
            app.text17 = uilabel(app.GridLayout4);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Applicable Event';
            app.text25 = uilabel(app.GridLayout4);
            app.text25.Tag = 'text25';
            app.text25.Text = 'Event Node';
            app.text15 = uilabel(app.GridLayout4);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Angle';
            app.text18 = uilabel(app.GridLayout4);
            app.text18.Tag = 'text18';
            app.text18.Tooltip = 'This number divides the value of the constraint.  Set such that the typical value of this constraint is 1.0.  Set to 1.0 if you''re unsure of what to put here.';
            app.text18.Text = 'Scale Factor';
            app.constraintTypeLabel = uilabel(app.GridLayout4);
            app.constraintTypeLabel.Tag = 'constraintTypeLabel';
            app.constraintTypeLabel.Text = 'ENGINE NAME';
            app.eventCombo = uidropdown(app.GridLayout4);
            app.eventCombo.Items = {};
            app.eventCombo.Tag = 'eventCombo';
            app.eventCombo.Value = {};
            app.eventNodeCombo = uidropdown(app.GridLayout4);
            app.eventNodeCombo.Items = {};
            app.eventNodeCombo.Tag = 'eventNodeCombo';
            app.eventNodeCombo.Tooltip = 'Selects which state during the event is used to evaluate the constraint: either the final state after propagation (the default) or the initial state prior to propagation.';
            app.eventNodeCombo.Value = {};
            app.angleCombo = uidropdown(app.GridLayout4);
            app.angleCombo.Items = {};
            app.angleCombo.Tag = 'angleCombo';
            app.angleCombo.Value = {};
            app.scaleFactorText = uieditfield(app.GridLayout4, 'text');
            app.scaleFactorText.ValueChangedFcn = createCallbackFcn(app, @scaleFactorText_Callback, true);
            app.scaleFactorText.Tag = 'scaleFactorText';
            app.scaleFactorText.Tooltip = 'This number divides the value of the constraint.  Set such that the typical value of this constraint is 1.0.  Set to 1.0 if you''re unsure of what to put here.';
            app.scaleFactorText.Value = '1.0';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.text19 = uilabel(app.GridLayout5);
            app.text19.Tag = 'text19';
            app.text19.Tooltip = 'This number divides the value of the constraint.  Set such that the typical value of this constraint is 1.0.  Set to 1.0 if you''re unsure of what to put here.';
            app.text19.Text = 'Evaluation Type';
            app.constraintEvalTypeCombo = uidropdown(app.GridLayout5);
            app.constraintEvalTypeCombo.Items = {'Pop-up Menu'};
            app.constraintEvalTypeCombo.ValueChangedFcn = createCallbackFcn(app, @constraintEvalTypeCombo_Callback, true);
            app.constraintEvalTypeCombo.Tag = 'constraintEvalTypeCombo';
            app.constraintEvalTypeCombo.Value = 'Pop-up Menu';
            app.useAbsValueCheckbox = uicheckbox(app.GridLayout3);
            app.useAbsValueCheckbox.Tag = 'useAbsValueCheckbox';
            app.useAbsValueCheckbox.Tooltip = 'Some angles may return negative angle measures depending on how they are defined.  When checked, the angle evaluated by this constraint will always be positive.';
            app.useAbsValueCheckbox.Text = 'Use Absolute Value of Angle';
            app.useAbsValueCheckbox.Value = true;
            app.constActiveCheckbox = uicheckbox(app.GridLayout3);
            app.constActiveCheckbox.Tag = 'constActiveCheckbox';
            app.constActiveCheckbox.Tooltip = 'An active constraint will influence the optimization process and be evaluated.  An inactive constraint does not influence the optimization process.';
            app.constActiveCheckbox.Text = 'Constraint Active?';
            app.constActiveCheckbox.Value = true;
            app.uipanel4 = uipanel(app.GridLayout3);
            app.uipanel4.Title = 'Reference Frame';
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout6 = uigridlayout(app.uipanel4);
            app.refFrameNameLabel = uilabel(app.GridLayout6);
            app.refFrameNameLabel.Tag = 'refFrameNameLabel';
            app.refFrameNameLabel.Text = 'Static Text';
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.text22 = uilabel(app.GridLayout7);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Frame Type';
            app.refFrameTypeCombo = uidropdown(app.GridLayout7);
            app.refFrameTypeCombo.Items = {};
            app.refFrameTypeCombo.ValueChangedFcn = createCallbackFcn(app, @refFrameTypeCombo_Callback, true);
            app.refFrameTypeCombo.Tag = 'refFrameTypeCombo';
            app.refFrameTypeCombo.Tooltip = 'This is the reference frame in which the selected quantity will be evaluated in.';
            app.refFrameTypeCombo.Value = {};
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.setFrameOptionsButton = uibutton(app.GridLayout8, 'push');
            app.setFrameOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @setFrameOptionsButton_Callback, true);
            app.setFrameOptionsButton.Tag = 'setFrameOptionsButton';
            app.setFrameOptionsButton.Icon = 'reference_frame.png';
            app.setFrameOptionsButton.Tooltip = 'Sets parameters for the selected reference frame type.';
            app.setFrameOptionsButton.Text = 'Set Frame Options';
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Fixed Bounds';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout9 = uigridlayout(app.uipanel2);
            app.text13 = uilabel(app.GridLayout9);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Upper Bound';
            app.text5 = uilabel(app.GridLayout9);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Lower Bound';
            app.ubText = uieditfield(app.GridLayout9, 'text');
            app.ubText.ValueChangedFcn = createCallbackFcn(app, @ubText_Callback, true);
            app.ubText.Tag = 'ubText';
            app.ubText.Value = '0.0';
            app.lbText = uieditfield(app.GridLayout9, 'text');
            app.lbText.ValueChangedFcn = createCallbackFcn(app, @lbText_Callback, true);
            app.lbText.Tag = 'lbText';
            app.lbText.Value = '0.0';
            app.ubUnitLabel = uilabel(app.GridLayout9);
            app.ubUnitLabel.Tag = 'ubUnitLabel';
            app.ubUnitLabel.Text = 'mT';
            app.lbUnitLabel = uilabel(app.GridLayout9);
            app.lbUnitLabel.Tag = 'lbUnitLabel';
            app.lbUnitLabel.Text = 'mT';
            app.uipanel3 = uipanel(app.GridLayout3);
            app.uipanel3.Title = 'State Comparison';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout10 = uigridlayout(app.uipanel3);
            app.text20 = uilabel(app.GridLayout10);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Comparison Event';
            app.text24 = uilabel(app.GridLayout10);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Comparison Node';
            app.text21 = uilabel(app.GridLayout10);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Comparison Type';
            app.compEventCombo = uidropdown(app.GridLayout10);
            app.compEventCombo.Items = {'Pop-up Menu'};
            app.compEventCombo.Tag = 'compEventCombo';
            app.compEventCombo.Tooltip = 'The constraint is satisfied when the value of the constraint at the end of the applicable event is equal to, less than, or greater than that of the comparison event, evaluated in the inertial reference frame of the applicable event.';
            app.compEventCombo.Value = 'Pop-up Menu';
            app.stateComparisonNodeCombo = uidropdown(app.GridLayout10);
            app.stateComparisonNodeCombo.Items = {'Pop-up Menu'};
            app.stateComparisonNodeCombo.Tag = 'stateComparisonNodeCombo';
            app.stateComparisonNodeCombo.Tooltip = 'Selects which state during the event is used to evaluate the constraint: either the final state after propagation (the default) or the initial state prior to propagation.';
            app.stateComparisonNodeCombo.Value = 'Pop-up Menu';
            app.comparisonTypeCombo = uidropdown(app.GridLayout10);
            app.comparisonTypeCombo.Items = {'Pop-up Menu'};
            app.comparisonTypeCombo.Tag = 'comparisonTypeCombo';
            app.comparisonTypeCombo.Value = 'Pop-up Menu';
            % Show the figure after all components are created
            app.lvd_EditGeometricAngleConstraintGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditGeometricAngleConstraintGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditGeometricAngleConstraintGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditGeometricAngleConstraintGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditGeometricAngleConstraintGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditGeometricAngleConstraintGUI)
        end
    end
end