classdef lvd_EditTankConstraintGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditGroundObjConstraintGUI  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        uipanel1                        matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        uipanel3                        matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        comparisonTypeCombo             matlab.ui.control.DropDown
        stateComparisonNodeCombo        matlab.ui.control.DropDown
        compEventCombo                  matlab.ui.control.DropDown
        text21                          matlab.ui.control.Label
        text24                          matlab.ui.control.Label
        text20                          matlab.ui.control.Label
        uipanel2                        matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        lbUnitLabel                     matlab.ui.control.Label
        ubUnitLabel                     matlab.ui.control.Label
        lbText                          matlab.ui.control.EditField
        ubText                          matlab.ui.control.EditField
        text5                           matlab.ui.control.Label
        text13                          matlab.ui.control.Label
        GridLayout4                     matlab.ui.container.GridLayout
        constraintEvalTypeCombo         matlab.ui.control.DropDown
        scaleFactorText                 matlab.ui.control.EditField
        tankCombo                       matlab.ui.control.DropDown
        eventNodeCombo                  matlab.ui.control.DropDown
        eventCombo                      matlab.ui.control.DropDown
        constraintTypeLabel             matlab.ui.control.Label
        text19                          matlab.ui.control.Label
        text18                          matlab.ui.control.Label
        text15                          matlab.ui.control.Label
        text25                          matlab.ui.control.Label
        text17                          matlab.ui.control.Label
        stageName                       matlab.ui.control.Label
        constActiveCheckbox             matlab.ui.control.CheckBox
        GridLayout2                     matlab.ui.container.GridLayout
        cancelButton                    matlab.ui.control.Button
        saveAndCloseButton              matlab.ui.control.Button
        text2                           matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, constraint, lvdData)
            handles.constraintTypeLabel.String = constraint.getConstraintType();
            handles.constraintTypeLabel.TooltipString = constraint.getConstraintType();
            [unit, ~, ~, usesLbUb, ~, ~] = constraint.getConstraintStaticDetails();

            if(usesLbUb)
                [lb, ub] = constraint.getBounds();
                handles.ubText.String = fullAccNum2Str(ub);
                handles.lbText.String = fullAccNum2Str(lb);
            else
                handles.ubText.String = fullAccNum2Str(0);
                handles.lbText.String = fullAccNum2Str(0);
                handles.lbText.Enable = 'off';
                handles.ubText.Enable = 'off';
            end

            handles.scaleFactorText.String = fullAccNum2Str(constraint.getScaleFactor());

            handles.ubUnitLabel.String = unit;
            handles.lbUnitLabel.String = unit;

            evtNum = lvdData.script.getNumOfEvent(constraint.event);
            handles.eventCombo.String = lvdData.script.getListboxStr();
            if(not(isempty(evtNum)))
                handles.eventCombo.Value = evtNum;
            else
                handles.eventCombo.Value = 1;
            end

            handles.tankCombo.String = lvdData.launchVehicle.getTanksListBoxStr();

            if(isempty(constraint.tank))
                tankInd = 1;
            else
                tankInd = lvdData.launchVehicle.getListBoxIndForTank(constraint.tank);
            end
            handles.tankCombo.Value = tankInd;

            handles.constraintEvalTypeCombo.String = ConstraintEvalTypeEnum.getListBoxStr();
            handles.constraintEvalTypeCombo.Value = ConstraintEvalTypeEnum.getIndForName(constraint.evalType.name);
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            constraintEvalTypeCombo_Callback(app, evt);

            evtNum = lvdData.script.getNumOfEvent(constraint.stateCompEvent);
            handles.compEventCombo.String = lvdData.script.getListboxStr();
            if(not(isempty(evtNum)))
                handles.compEventCombo.Value = evtNum;
            else
                handles.compEventCombo.Value = 1;
            end

            handles.eventNodeCombo.String = ConstraintStateComparisonNodeEnum.getListBoxStr();
            handles.eventNodeCombo.Value = ConstraintStateComparisonNodeEnum.getIndForName(constraint.eventNode.name);
            
            handles.comparisonTypeCombo.String = ConstraintStateComparisonTypeEnum.getListBoxStr();
            handles.comparisonTypeCombo.Value = ConstraintStateComparisonTypeEnum.getIndForName(constraint.stateCompType.name);

            handles.stateComparisonNodeCombo.String = ConstraintStateComparisonNodeEnum.getListBoxStr();
            handles.stateComparisonNodeCombo.Value = ConstraintStateComparisonNodeEnum.getIndForName(constraint.stateCompNode.name);

            handles.comparisonTypeCombo.String = ConstraintStateComparisonTypeEnum.getListBoxStr();
            handles.comparisonTypeCombo.Value = ConstraintStateComparisonTypeEnum.getIndForName(constraint.stateCompType.name);

            handles.constActiveCheckbox.Value = constraint.active;
        end

        function refFrameTypeCombo_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to refFrameTypeCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            constraint = getappdata(handles.lvd_EditGroundObjConstraintGUI, 'constraint');
            [~, lbLim, ubLim, usesLbUb, ~, ~] = constraint.getConstraintStaticDetails();

            if(usesLbUb)
                lwrBnd = str2double(get(handles.lbText,'String'));
                enteredStr = get(handles.lbText,'String');
                numberName = 'Lower Bound';
                lb = lbLim;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(lwrBnd, numberName, lb, ub, isInt, errMsg, enteredStr);

                uprBnd = str2double(get(handles.ubText,'String'));
                enteredStr = get(handles.ubText,'String');
                numberName = 'Upper Bound';
                lb = -Inf;
                ub = ubLim;
                isInt = false;
                errMsg = validateNumber(uprBnd, numberName, lb, ub, isInt, errMsg, enteredStr);

                if(isempty(errMsg))
                    uprBnd = str2double(get(handles.ubText,'String'));
                    enteredStr = get(handles.ubText,'String');
                    numberName = 'Upper Bound';
                    lb = lwrBnd;
                    ub = ubLim;
                    isInt = false;
                    errMsg = validateNumber(uprBnd, numberName, lb, ub, isInt, errMsg, enteredStr);
                end
            end

            sf = str2double(get(handles.scaleFactorText,'String'));
            enteredStr = get(handles.scaleFactorText,'String');
            numberName = 'Scale Factor';
            lb = 1E-12;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(sf, numberName, lb, ub, isInt, errMsg, enteredStr);

            [~, enums] = ConstraintEvalTypeEnum.getListBoxStr();
            evalTypeEnum = enums(handles.constraintEvalTypeCombo.Value);
            if(evalTypeEnum == ConstraintEvalTypeEnum.StateComparison && ...
                    handles.eventCombo.Value == handles.compEventCombo.Value && ...
                    handles.eventNodeCombo.Value == handles.stateComparisonNodeCombo.Value)
                errMsg{end+1} = 'When using the state comparison evaluation type, the Applicable Event and Node and Comparison Event and Node must be different.';
            end
        end


        function saveAndClose(app, handles)
            constraint = getappdata(handles.lvd_EditGroundObjConstraintGUI, 'constraint');
            lvdData = getappdata(handles.lvd_EditGroundObjConstraintGUI,'lvdData');

            constraint.lb = str2double(handles.lbText.String);
            constraint.ub = str2double(handles.ubText.String);

            constraint.setScaleFactor(str2double(handles.scaleFactorText.String));

            constraint.tank = lvdData.launchVehicle.getTankForInd(handles.tankCombo.Value);

            [~, enums] = ConstraintEvalTypeEnum.getListBoxStr();
            constraint.evalType = enums(handles.constraintEvalTypeCombo.Value);

            [~, enums] = ConstraintStateComparisonTypeEnum.getListBoxStr();
            constraint.stateCompType = enums(handles.comparisonTypeCombo.Value);

            [~, enums] = ConstraintStateComparisonNodeEnum.getListBoxStr();
            constraint.eventNode = enums(handles.eventNodeCombo.Value);

            [~, enums] = ConstraintStateComparisonNodeEnum.getListBoxStr();
            constraint.stateCompNode = enums(handles.stateComparisonNodeCombo.Value);

            numEvents = lvdData.script.getTotalNumOfEvents();
            if(numEvents > 0)
                eventNum = handles.eventCombo.Value;
                constraint.event = lvdData.script.getEventForInd(eventNum);

                if(constraint.evalType == ConstraintEvalTypeEnum.StateComparison)
                    eventNum = handles.compEventCombo.Value;
                    constraint.stateCompEvent = lvdData.script.getEventForInd(eventNum);
                else
                    constraint.stateCompEvent = LaunchVehicleEvent.empty(1,0);
                end
            else
                constraint.event = LaunchVehicleEvent.empty(1,0);
                constraint.stateCompEvent = LaunchVehicleEvent.empty(1,0);
            end

            constraint.active = logical(handles.constActiveCheckbox.Value);

            app.output.output = {true};
            close(handles.lvd_EditGroundObjConstraintGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditTankConstraintGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditTankConstraintGUI (see VARARGIN)

            % Choose default command line output for lvd_EditTankConstraintGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            constraint = varargin{1};
            setappdata(hObject, 'constraint', constraint);

            lvdData = varargin{2};
            setappdata(hObject,'lvdData',lvdData);

            app.output = varargin{3};

            populateGUI(app, handles, constraint, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditTankConstraintGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditGroundObjConstraintGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditGroundObjConstraintGUI);
        end

        % Value changed function: constraintEvalTypeCombo
        function constraintEvalTypeCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.constraintEvalTypeCombo;

            % hObject    handle to constraintEvalTypeCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns constraintEvalTypeCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from constraintEvalTypeCombo
            contents = cellstr(get(hObject,'String'));
            name = contents{get(hObject,'Value')};

            enum = ConstraintEvalTypeEnum.getEnumForListboxStr(name);
            switch enum
                case ConstraintEvalTypeEnum.FixedBounds
                    handles.ubText.Enable = 'on';
                    handles.lbText.Enable = 'on';
                    handles.compEventCombo.Enable = 'off';
                    handles.comparisonTypeCombo.Enable = 'off';
                    handles.stateComparisonNodeCombo.Enable = 'off';

                case ConstraintEvalTypeEnum.StateComparison
                    handles.ubText.Enable = 'off';
                    handles.lbText.Enable = 'off';
                    handles.compEventCombo.Enable = 'on';
                    handles.comparisonTypeCombo.Enable = 'on';
                    handles.stateComparisonNodeCombo.Enable = 'on';

                otherwise
                    error('Unknown constraint evaluation type.');
            end
        end

        % Value changed function: lbText
        function lbText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lbText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of lbText as text
            %        str2double(get(hObject,'String')) returns contents of lbText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Window key press function: lvd_EditGroundObjConstraintGUI
        function lvd_EditGroundObjConstraintGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditTankConstraintGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditGroundObjConstraintGUI);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                %                 uiresume(handles.lvd_EditGroundObjConstraintGUI);
                app.saveAndClose(handles);
            else
                %                 msgbox(errMsg,'Invalid Constraint Inputs','error');
                uialert(app.lvd_EditGroundObjConstraintGUI, errMsg, 'Invalid Constraint Inputs', "Icon","error");
            end
        end

        % Value changed function: scaleFactorText
        function scaleFactorText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to scaleFactorText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of scaleFactorText as text
            %        str2double(get(hObject,'String')) returns contents of scaleFactorText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: ubText
        function ubText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to ubText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of ubText as text
            %        str2double(get(hObject,'String')) returns contents of ubText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditGroundObjConstraintGUI = uifigure('Visible', 'off');
            app.lvd_EditGroundObjConstraintGUI.Name = 'Edit Constraint';
            app.lvd_EditGroundObjConstraintGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditGroundObjConstraintGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditGroundObjConstraintGUI_WindowKeyPressFcn, true);
            app.lvd_EditGroundObjConstraintGUI.Tag = 'lvd_EditGroundObjConstraintGUI';
            app.GridLayout = uigridlayout(app.lvd_EditGroundObjConstraintGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Constraint';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.constActiveCheckbox = uicheckbox(app.GridLayout3);
            app.constActiveCheckbox.Tag = 'constActiveCheckbox';
            app.constActiveCheckbox.Tooltip = 'An active constraint will influence the optimization process and be evaluated.  An inactive constraint does not influence the optimization process.';
            app.constActiveCheckbox.Text = 'Constraint Active?';
            app.constActiveCheckbox.Value = true;
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.stageName = uilabel(app.GridLayout4);
            app.stageName.Tag = 'stageName';
            app.stageName.Text = 'Constraint Type';
            app.text17 = uilabel(app.GridLayout4);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Applicable Event';
            app.text25 = uilabel(app.GridLayout4);
            app.text25.Tag = 'text25';
            app.text25.Text = 'Event Node';
            app.text15 = uilabel(app.GridLayout4);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Tank';
            app.text18 = uilabel(app.GridLayout4);
            app.text18.Tag = 'text18';
            app.text18.Tooltip = 'This number divides the value of the constraint.  Set such that the typical value of this constraint is 1.0.  Set to 1.0 if you''re unsure of what to put here.';
            app.text18.Text = 'Scale Factor';
            app.text19 = uilabel(app.GridLayout4);
            app.text19.Tag = 'text19';
            app.text19.Tooltip = 'This number divides the value of the constraint.  Set such that the typical value of this constraint is 1.0.  Set to 1.0 if you''re unsure of what to put here.';
            app.text19.Text = 'Evaluation Type';
            app.constraintTypeLabel = uilabel(app.GridLayout4);
            app.constraintTypeLabel.Tag = 'constraintTypeLabel';
            app.constraintTypeLabel.Text = 'ENGINE NAME';
            app.eventCombo = uidropdown(app.GridLayout4);
            app.eventCombo.Items = {};
            app.eventCombo.Tag = 'eventCombo';
            app.eventCombo.Value = {};
            app.eventNodeCombo = uidropdown(app.GridLayout4);
            app.eventNodeCombo.Items = {};
            app.eventNodeCombo.Tag = 'eventNodeCombo';
            app.eventNodeCombo.Tooltip = 'Selects which state during the event is used to evaluate the constraint: either the final state after propagation (the default) or the initial state prior to propagation.';
            app.eventNodeCombo.Value = {};
            app.tankCombo = uidropdown(app.GridLayout4);
            app.tankCombo.Items = {};
            app.tankCombo.Tag = 'tankCombo';
            app.tankCombo.Value = {};
            app.scaleFactorText = uieditfield(app.GridLayout4, 'text');
            app.scaleFactorText.ValueChangedFcn = createCallbackFcn(app, @scaleFactorText_Callback, true);
            app.scaleFactorText.Tag = 'scaleFactorText';
            app.scaleFactorText.Tooltip = 'This number divides the value of the constraint.  Set such that the typical value of this constraint is 1.0.  Set to 1.0 if you''re unsure of what to put here.';
            app.scaleFactorText.Value = '1.0';
            app.constraintEvalTypeCombo = uidropdown(app.GridLayout4);
            app.constraintEvalTypeCombo.Items = {'Pop-up Menu'};
            app.constraintEvalTypeCombo.ValueChangedFcn = createCallbackFcn(app, @constraintEvalTypeCombo_Callback, true);
            app.constraintEvalTypeCombo.Tag = 'constraintEvalTypeCombo';
            app.constraintEvalTypeCombo.Value = 'Pop-up Menu';
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Fixed Bounds';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout5 = uigridlayout(app.uipanel2);
            app.text13 = uilabel(app.GridLayout5);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Upper Bound';
            app.text5 = uilabel(app.GridLayout5);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Lower Bound';
            app.ubText = uieditfield(app.GridLayout5, 'text');
            app.ubText.ValueChangedFcn = createCallbackFcn(app, @ubText_Callback, true);
            app.ubText.Tag = 'ubText';
            app.ubText.Value = '0.0';
            app.lbText = uieditfield(app.GridLayout5, 'text');
            app.lbText.ValueChangedFcn = createCallbackFcn(app, @lbText_Callback, true);
            app.lbText.Tag = 'lbText';
            app.lbText.Value = '0.0';
            app.ubUnitLabel = uilabel(app.GridLayout5);
            app.ubUnitLabel.Tag = 'ubUnitLabel';
            app.ubUnitLabel.Text = 'mT';
            app.lbUnitLabel = uilabel(app.GridLayout5);
            app.lbUnitLabel.Tag = 'lbUnitLabel';
            app.lbUnitLabel.Text = 'mT';
            app.uipanel3 = uipanel(app.GridLayout3);
            app.uipanel3.Title = 'State Comparison';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout6 = uigridlayout(app.uipanel3);
            app.text20 = uilabel(app.GridLayout6);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Comparison Event';
            app.text24 = uilabel(app.GridLayout6);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Comparison Node';
            app.text21 = uilabel(app.GridLayout6);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Comparison Type';
            app.compEventCombo = uidropdown(app.GridLayout6);
            app.compEventCombo.Items = {'Pop-up Menu'};
            app.compEventCombo.Tag = 'compEventCombo';
            app.compEventCombo.Tooltip = 'The constraint is satisfied when the value of the constraint at the end of the applicable event is equal to, less than, or greater than that of the comparison event, evaluated in the inertial reference frame of the applicable event.';
            app.compEventCombo.Value = 'Pop-up Menu';
            app.stateComparisonNodeCombo = uidropdown(app.GridLayout6);
            app.stateComparisonNodeCombo.Items = {'Pop-up Menu'};
            app.stateComparisonNodeCombo.Tag = 'stateComparisonNodeCombo';
            app.stateComparisonNodeCombo.Tooltip = 'Selects which state during the event is used to evaluate the constraint: either the final state after propagation (the default) or the initial state prior to propagation.';
            app.stateComparisonNodeCombo.Value = 'Pop-up Menu';
            app.comparisonTypeCombo = uidropdown(app.GridLayout6);
            app.comparisonTypeCombo.Items = {'Pop-up Menu'};
            app.comparisonTypeCombo.Tag = 'comparisonTypeCombo';
            app.comparisonTypeCombo.Value = 'Pop-up Menu';
            % Show the figure after all components are created
            app.lvd_EditGroundObjConstraintGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditTankConstraintGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditGroundObjConstraintGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditTankConstraintGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditGroundObjConstraintGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditGroundObjConstraintGUI)
        end
    end
end