classdef lvd_adjustOptVarGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_adjustOptVarGUI  matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        uipanel1             matlab.ui.container.Panel
        GridLayout3          matlab.ui.container.GridLayout
        GridLayout4          matlab.ui.container.GridLayout
        varUpperBndLabel     matlab.ui.control.Label
        varLowerBndLabel     matlab.ui.control.Label
        varValueSlider       matlab.ui.control.Slider
        varValueText         matlab.ui.control.EditField
        variablesCombo       matlab.ui.control.DropDown
        variableSearchText          matlab.ui.control.EditField
        text6                matlab.ui.control.Label
        GridLayout2          matlab.ui.container.GridLayout
        cancelButton         matlab.ui.control.Button
        saveAndCloseButton   matlab.ui.control.Button
        text2                matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, lvdData, value, query)
            lvdOpt = lvdData.optimizer;
            varSet = lvdOpt.vars;
            varSet.sortVarsByEvtNum();
            [~, ~, varNameStrs, xUnscaled] = varSet.getTotalScaledXVector();
            if(nargin < 5)
                query = app.variableSearchText.Value;
            end
            [~, ~, lwrBndsUnscaled, uprBndsUnscaled] = varSet.getTotalScaledBndsVector();
            visibleXInds = find(lvd_filterListboxItems(query, varNameStrs));
            if(isempty(visibleXInds))
                app.variablesCombo.ItemsData = [];
                app.variablesCombo.Items = {};
                app.variablesCombo.Enable = 'off';
                handles.varValueText.String = 'No matching variables.';
                app.varValueText.Enable = 'off';
                handles.varLowerBndLabel.String = '';
                handles.varUpperBndLabel.String = '';
                app.varValueSlider.Enable = 'off';
                app.saveAndCloseButton.Enable = 'off';
                return;
            end
            
            if(isempty(value) || not(isnumeric(value)) || not(isscalar(value)) || not(any(value == visibleXInds)))
                value = visibleXInds(1);
            end
 
            if(xUnscaled(value) < lwrBndsUnscaled(value))
                xUnscaled(value) = lwrBndsUnscaled(value);
            elseif(xUnscaled(value) > uprBndsUnscaled(value))
                xUnscaled(value) = uprBndsUnscaled(value);
            end
 
            if(lwrBndsUnscaled(value) == uprBndsUnscaled(value))
                xUnscaled(value) = lwrBndsUnscaled(value);
 
                lwrBndsUnscaled(value) = lwrBndsUnscaled(value) - 1E-12;
                uprBndsUnscaled(value) = uprBndsUnscaled(value) + 1E-12;
            end
 
            %if vars are stored in radians, we want to display them in
            %degrees.
            varsStoredInRad = varSet.getVarsStoredInRad();
            varsDisplayedInPercent = varSet.getVarsDisplayedAsPercents();
            varsDisplayedInMeters = varSet.getVarsDisplayedAsMeters();
 
            xUnscaledInDisplayUnits = xUnscaled;
            lwrBndsUnscaledInDisplayUnits = lwrBndsUnscaled;
            uprBndsUnscaledInDisplayUnits = uprBndsUnscaled;
            if(varsStoredInRad(value))
                xUnscaledInDisplayUnits(value) = rad2deg(xUnscaled(value));
                lwrBndsUnscaledInDisplayUnits(value) = rad2deg(lwrBndsUnscaled(value));
                uprBndsUnscaledInDisplayUnits(value) = rad2deg(uprBndsUnscaled(value));
 
            elseif(varsDisplayedInPercent(value))
                xUnscaledInDisplayUnits(value) = 100*(xUnscaled(value));
                lwrBndsUnscaledInDisplayUnits(value) = 100*(lwrBndsUnscaled(value));
                uprBndsUnscaledInDisplayUnits(value) = 100*(uprBndsUnscaled(value));
 
            elseif(varsDisplayedInMeters(value))
                xUnscaledInDisplayUnits(value) = 1000*(xUnscaled(value));
                lwrBndsUnscaledInDisplayUnits(value) = 1000*(lwrBndsUnscaled(value));
                uprBndsUnscaledInDisplayUnits(value) = 1000*(uprBndsUnscaled(value));
            end
 
            app.variablesCombo.ItemsData = visibleXInds;
            app.variablesCombo.Items = varNameStrs(visibleXInds);
            app.variablesCombo.Value = value;
            app.variablesCombo.Enable = 'on';
            app.varValueText.Enable = 'on';
            handles.varValueText.String = fullAccNum2Str(xUnscaledInDisplayUnits(value));
            handles.varLowerBndLabel.String = fullAccNum2Str(lwrBndsUnscaledInDisplayUnits(value));
            handles.varUpperBndLabel.String = fullAccNum2Str(uprBndsUnscaledInDisplayUnits(value));
            handles.varValueSlider.Value = scaleXToNeg1And1(app, xUnscaled(value), lwrBndsUnscaled(value), uprBndsUnscaled(value));
            app.varValueSlider.Enable = 'on';
            app.saveAndCloseButton.Enable = 'on';
            
            app.varValueSlider.MajorTicks = linspace(-1,1,10);
        end

        function processUserChangeToVar(app, handles)
            propScriptFcn = getappdata(handles.lvd_adjustOptVarGUI,'propScriptFcn');
            procDataFcn = getappdata(handles.lvd_adjustOptVarGUI,'procDataFcn');
            lvdData = getappdata(handles.lvd_adjustOptVarGUI,'lvdData');
            lvdOpt = lvdData.optimizer;
            varSet = lvdOpt.vars;
            [xVectScaled, ~, ~, ~] = varSet.getTotalScaledXVector();
            [~, ~, lwrBndsUnscaled, uprBndsUnscaled] = varSet.getTotalScaledBndsVector();

            value = app.variablesCombo.Value;
            xScaled = handles.varValueSlider.Value;
            xUnscaled = unscaleXFromNeg1And1(app, xScaled, lwrBndsUnscaled(value), uprBndsUnscaled(value));

            xVectScaledUpdated = xVectScaled;
            xVectScaledUpdated(value) = xScaled;

            %if vars are stored in radians, we want to display them in
            %degrees
            varsStoredInRad = varSet.getVarsStoredInRad();
            varsDisplayedInPercent = varSet.getVarsDisplayedAsPercents();
            varsDisplayedInMeters = varSet.getVarsDisplayedAsMeters();

            xUnscaledInDisplayUnits = xUnscaled;
            if(varsStoredInRad(value))
                xUnscaledInDisplayUnits = rad2deg(xUnscaled);

            elseif(varsDisplayedInPercent(value))
                xUnscaledInDisplayUnits = 100*(xUnscaled);

            elseif(varsDisplayedInMeters(value))
                xUnscaledInDisplayUnits = 1000*(xUnscaled);
            end

            handles.varValueText.String = fullAccNum2Str(xUnscaledInDisplayUnits);

            varSet.updateObjsWithScaledVarValues(xVectScaledUpdated);
            propScriptFcn();
            procDataFcn();
            drawnow;
        end

        function xScaled = scaleXToNeg1And1(app, xi, lbi, ubi)
            bndDiff = ubi - lbi;
            bndCenter = (lbi + ubi)/2;
            xScaled = (xi - bndCenter)/(bndDiff/2);
        end

        function xUnscaled = unscaleXFromNeg1And1(app, xSi, lbi, ubi)
            bndDiff = ubi - lbi;
            bndCenter = (lbi + ubi)/2;

            if(bndDiff > 1E-10)
                xUnscaled = xSi * (bndDiff/2) + bndCenter;
            end
        end

        function varListUpdatedEventCallback(app, src, evtData, handles, lvdData)
            oldValue = app.variablesCombo.Value;
            if(not(isnumeric(oldValue)) || not(isscalar(oldValue)))
                oldValue = 1;
            end
            populateGUI(app, handles, lvdData, oldValue, app.variableSearchText.Value);
        end

        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)

            % Set component properties that require runtime configuration
            app.lvd_adjustOptVarGUI.DeleteFcn = createCallbackFcn(app, @lvd_adjustOptVarGUI_DeleteFcn, true);
        end

        function saveAndClose(app, handles)
            app.output.output = {true};
            close(handles.lvd_adjustOptVarGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_adjustOptVarGUI_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_adjustOptVarGUI (see VARARGIN)

            % Choose default command line output for lvd_adjustOptVarGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            propScriptFcn = varargin{2};
            setappdata(hObject,'propScriptFcn',propScriptFcn);

            procDataFcn = varargin{3};
            setappdata(hObject,'procDataFcn',procDataFcn);

            app.output = varargin{4};

            lvdOpt = lvdData.optimizer;
            varSet = lvdOpt.vars;
            [xVectScaled, origVars, ~, ~] = varSet.getTotalScaledXVector();
            setappdata(hObject,'origScaledXVect',xVectScaled);
            setappdata(hObject,'origVars',origVars);

            populateGUI(app, handles, lvdData, 1); %just close and throw an error if no variables enabled in LVD GUI

            hVarUpdatedListenerAdded = addlistener(varSet,'VarsListUpdatedAddedVar',@(src,evtData) varListUpdatedEventCallback(app, src,evtData, handles, lvdData));
            setappdata(hObject,'hVarUpdatedListenerAdded',hVarUpdatedListenerAdded);

            hVarUpdatedListenerRemoved = addlistener(varSet,'VarsListUpdatedRemovedVar',@(src,evtData) varListUpdatedEventCallback(app, src,evtData, handles, lvdData));
            setappdata(hObject,'hVarUpdatedListenerRemoved',hVarUpdatedListenerRemoved);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_adjustOptVarGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_adjustOptVarGUI);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
%             hObject = handles.cancelButton;
            
            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_adjustOptVarGUI,'lvdData');
            lvdOpt = lvdData.optimizer;
            varSet = lvdOpt.vars;

            origScaledXVect = getappdata(handles.lvd_adjustOptVarGUI,'origScaledXVect');
            origVars = getappdata(handles.lvd_adjustOptVarGUI,'origVars');

            [~, curVars, ~, ~] = varSet.getTotalScaledXVector();
            for(i=1:length(curVars)) %#ok<*NO4LP> 
                ind = find(curVars(i) == origVars);

                if(not(isempty(ind)))
                    curVars(i).updateObjWithScaledVarValue(origScaledXVect(ind));
                end
            end

            %     varSet.updateObjsWithScaledVarValues(origScaledXVect);

            close(handles.lvd_adjustOptVarGUI);
        end

        % Callback function
        function lvd_adjustOptVarGUI_DeleteFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_adjustOptVarGUI (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            hVarUpdatedListenerAdded = getappdata(hObject,'hVarUpdatedListenerAdded');
            delete(hVarUpdatedListenerAdded);

            hVarUpdatedListenerRemoved = getappdata(hObject,'hVarUpdatedListenerRemoved');
            delete(hVarUpdatedListenerRemoved);
        end

        % Window key press function: lvd_adjustOptVarGUI
        function lvd_adjustOptVarGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
 
            % hObject    handle to figure
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            switch(eventdata.Key)
                case 'return'
                    if(not(any(app.lvd_adjustOptVarGUI.CurrentObject == app.variableSearchText)))
                        saveAndCloseButton_Callback(app, evt);
                    end
                case 'enter'
                    if(not(any(app.lvd_adjustOptVarGUI.CurrentObject == app.variableSearchText)))
                        saveAndCloseButton_Callback(app, evt);
                    end
                case 'escape'
                    cancelButton_Callback(app, evt);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
%             uiresume(handles.lvd_adjustOptVarGUI);
            app.saveAndClose(handles);
        end

        % Value changed function: varValueSlider
        function varValueSlider_Callback(app, event)
            if(isa(event,'matlab.ui.eventdata.ValueChangedData'))
                % Create GUIDE-style callback args - Added by Migration Tool
                [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
    
                % hObject    handle to varValueSlider (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
    
                % Hints: get(hObject,'Value') returns position of slider
                %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
    
                processUserChangeToVar(app, handles);
    %             figure(handles.lvd_adjustOptVarGUI);
            end
        end

        % Value changed function: varValueText
        function varValueText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to varValueText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of varValueText as text
            %        str2double(get(hObject,'String')) returns contents of varValueText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            lvdData = getappdata(handles.lvd_adjustOptVarGUI,'lvdData');
            lvdOpt = lvdData.optimizer;
            varSet = lvdOpt.vars;
            [~, ~, ~, xUnscaled] = varSet.getTotalScaledXVector();
            [~, ~, lwrBndsUnscaled, uprBndsUnscaled] = varSet.getTotalScaledBndsVector();

            varInd = app.variablesCombo.Value;

            errMsg = {};
            value = str2double(get(handles.varValueText,'String'));
            enteredStr = get(handles.varValueText,'String');
            numberName = 'Epoch (UT)';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                lb = lwrBndsUnscaled(varInd);
                ub = uprBndsUnscaled(varInd);

                %if vars are stored in radians, we want to display them in
                %degrees
                varsStoredInRad = varSet.getVarsStoredInRad();
                varsDisplayedInPercent = varSet.getVarsDisplayedAsPercents();
                varsDisplayedInMeters = varSet.getVarsDisplayedAsMeters();

                valueInStorageUnits = value;
                if(varsStoredInRad(varInd))
                    valueInStorageUnits = deg2rad(value);

                elseif(varsDisplayedInPercent(varInd))
                    valueInStorageUnits = value/100;

                elseif(varsDisplayedInMeters(varInd))
                    valueInStorageUnits = value/1000;
                end

                if(valueInStorageUnits < lb)
                    handles.varValueText.String = fullAccNum2Str(lb);
                    handles.varValueSlider.Value = -1;

                elseif(valueInStorageUnits > ub)
                    handles.varValueText.String = fullAccNum2Str(ub);
                    handles.varValueSlider.Value = 1;

                else
                    handles.varValueSlider.Value = scaleXToNeg1And1(app, valueInStorageUnits, lb, ub);
                end

                processUserChangeToVar(app, handles);
            else
                handles.varValueText.String = fullAccNum2Str(xUnscaled(varInd));
            end
        end

        % Value changed function: variablesCombo
        function variablesCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to variablesCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns variablesCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from variablesCombo
            lvdData = getappdata(handles.lvd_adjustOptVarGUI,'lvdData');
            value = app.variablesCombo.Value;
            populateGUI(app, handles, lvdData, value);
        end

 
        % Value changing function: variableSearchText
        function variableSearchTextValueChanging(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            lvdData = getappdata(handles.lvd_adjustOptVarGUI,'lvdData');
            populateGUI(app, handles, lvdData, app.variablesCombo.Value, event.Value);
        end
        % Value changed function: variableSearchText
        function variableSearchTextValueChanged(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            lvdData = getappdata(handles.lvd_adjustOptVarGUI,'lvdData');
            populateGUI(app, handles, lvdData, app.variablesCombo.Value, event.Value);
        end
    end
    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_adjustOptVarGUI = uifigure('Visible', 'off');
            app.lvd_adjustOptVarGUI.Name = 'Adjust Variables';
            app.lvd_adjustOptVarGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_adjustOptVarGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_adjustOptVarGUI_WindowKeyPressFcn, true);
            app.lvd_adjustOptVarGUI.Tag = 'lvd_adjustOptVarGUI';
            app.GridLayout = uigridlayout(app.lvd_adjustOptVarGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Adjust Variables';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.text6 = uilabel(app.GridLayout);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Warning: Close and reopen this dialog box if optimization variables are added, removed, enabled, or disabled.';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.variableSearchText = uieditfield(app.GridLayout3, 'text');
            app.variableSearchText.ValueChangingFcn = createCallbackFcn(app, @variableSearchTextValueChanging, true);
            app.variableSearchText.ValueChangedFcn = createCallbackFcn(app, @variableSearchTextValueChanged, true);
            app.variableSearchText.Tag = 'variableSearchText';
            app.variableSearchText.Tooltip = 'Type to filter the optimization variables below.';
            app.variableSearchText.Placeholder = 'Search variables...';
            app.variablesCombo = uidropdown(app.GridLayout3);
            app.variablesCombo.Items = {'Pop-up Menu'};
            app.variablesCombo.ValueChangedFcn = createCallbackFcn(app, @variablesCombo_Callback, true);
            app.variablesCombo.Tag = 'variablesCombo';
            app.variablesCombo.Value = 'Pop-up Menu';
            app.varValueText = uieditfield(app.GridLayout3, 'text');
            app.varValueText.ValueChangedFcn = createCallbackFcn(app, @varValueText_Callback, true);
            app.varValueText.Tag = 'varValueText';
            app.varValueText.Value = 'Static Text';
            app.varValueSlider = uislider(app.GridLayout3);
            app.varValueSlider.Limits = [-1 1];
            app.varValueSlider.MajorTicks = [];
            app.varValueSlider.MajorTickLabels = {''};
            app.varValueSlider.ValueChangedFcn = createCallbackFcn(app, @varValueSlider_Callback, true);
            app.varValueSlider.MinorTicks = [];
            app.varValueSlider.Tag = 'varValueSlider';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.varLowerBndLabel = uilabel(app.GridLayout4);
            app.varLowerBndLabel.Tag = 'varLowerBndLabel';
            app.varLowerBndLabel.Text = 'Static Text';
            app.varUpperBndLabel = uilabel(app.GridLayout4);
            app.varUpperBndLabel.Tag = 'varUpperBndLabel';
            app.varUpperBndLabel.Text = 'Static Text';
            % Show the figure after all components are created
            app.lvd_adjustOptVarGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_adjustOptVarGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_adjustOptVarGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_adjustOptVarGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_adjustOptVarGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_adjustOptVarGUI)
        end
    end
end