classdef lvd_OptimizerSelectionGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_OptimizerSelectionGUI    matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        uipanel1                     matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        uipanel3                     matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        editSelectedGradAlgoOptions  matlab.ui.control.Button
        optimGradAlgDescTextLbl      matlab.ui.control.Label
        gradientSelCombo             matlab.ui.control.DropDown
        text5                        matlab.ui.control.Label
        uipanel2                     matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        editSelectedOptimizerOptionsButton  matlab.ui.control.Button
        optimAlgDescTextLbl          matlab.ui.control.Label
        optimizerSelectionCombo      matlab.ui.control.DropDown
        text3                        matlab.ui.control.Label
        saveAndCloseButton           matlab.ui.control.Button
        text2                        matlab.ui.control.Label
    end


    methods (Access = private)
        function handles = populateGUI(app, lvdData, handles)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            lvdOpt = lvdData.optimizer;
            optAlgoStrs = LvdOptimizerAlgoEnum.getListBoxStr();
            gradAlgoStrs = LvdOptimizerGradientCalculationAlgoEnum.getListBoxStr();

            handles.gradientSelCombo.String = gradAlgoStrs;
            handles.gradientSelCombo.Value = LvdOptimizerGradientCalculationAlgoEnum.getIndForName(lvdOpt.gradAlgo.name);
            gradientSelCombo_Callback(app, evt);

            handles.optimizerSelectionCombo.String = optAlgoStrs;
            handles.optimizerSelectionCombo.Value = LvdOptimizerAlgoEnum.getIndForName(lvdOpt.optAlgo.name);
            optimizerSelectionCombo_Callback(app, evt);
        end

        function saveAndClose(app, handles)
            lvdData = getappdata(handles.lvd_OptimizerSelectionGUI, 'lvdData');
            lvdOpt = lvdData.optimizer;

            gradAlgoStrs = LvdOptimizerGradientCalculationAlgoEnum.getListBoxStr();
            optGradInd = handles.gradientSelCombo.Value;
            gradAlgoEnum = LvdOptimizerGradientCalculationAlgoEnum.getEnumForListboxStr(gradAlgoStrs{optGradInd});
            lvdOpt.gradAlgo = gradAlgoEnum;

            optAlgoStrs = LvdOptimizerAlgoEnum.getListBoxStr();
            optAlgoInd = handles.optimizerSelectionCombo.Value;
            optAlgoEnum = LvdOptimizerAlgoEnum.getEnumForListboxStr(optAlgoStrs{optAlgoInd});
            lvdOpt.optAlgo = optAlgoEnum;

            close(handles.lvd_OptimizerSelectionGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_OptimizerSelectionGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_OptimizerSelectionGUI (see VARARGIN)

            % Choose default command line output for lvd_OptimizerSelectionGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            lvdData = varargin{1};
            setappdata(hObject, 'lvdData', lvdData);

            handles = populateGUI(app, lvdData, handles);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_OptimizerSelectionGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_OptimizerSelectionGUI);
        end

        % Button pushed function: editSelectedGradAlgoOptions
        function editSelectedGradAlgoOptions_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editSelectedGradAlgoOptions (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_OptimizerSelectionGUI, 'lvdData');

            gradAlgoStrs = LvdOptimizerGradientCalculationAlgoEnum.getListBoxStr();
            gradAlgoInd = handles.gradientSelCombo.Value;
            gradAlgoEnum = LvdOptimizerGradientCalculationAlgoEnum.getEnumForListboxStr(gradAlgoStrs{gradAlgoInd});

            lvdOpt = lvdData.optimizer;
            gradAlgo = lvdOpt.getGradAlgoForEnum(gradAlgoEnum);
            gradAlgo.openOptionsDialog();
        end

        % Button pushed function: editSelectedOptimizerOptionsButton
        function editSelectedOptimizerOptionsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editSelectedOptimizerOptionsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_OptimizerSelectionGUI, 'lvdData');

            optAlgoStrs = LvdOptimizerAlgoEnum.getListBoxStr();
            optAlgoInd = handles.optimizerSelectionCombo.Value;
            optAlgoEnum = LvdOptimizerAlgoEnum.getEnumForListboxStr(optAlgoStrs{optAlgoInd});

            lvdOpt = lvdData.optimizer;
            optimizer = lvdOpt.getOptimizerForEnum(optAlgoEnum);
            optimizer.openOptionsDialog();
        end

        % Value changed function: gradientSelCombo
        function gradientSelCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.gradientSelCombo;

            % hObject    handle to gradientSelCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns gradientSelCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from gradientSelCombo
            gradAlgoStrs = LvdOptimizerGradientCalculationAlgoEnum.getListBoxStr();
            enum = LvdOptimizerGradientCalculationAlgoEnum.getEnumForListboxStr(gradAlgoStrs{get(hObject,'Value')});
            handles.optimGradAlgDescTextLbl.String = enum.desc;

            if(enum.disableOptions)
                handles.editSelectedGradAlgoOptions.Enable = 'off';
            else
                handles.editSelectedGradAlgoOptions.Enable = 'on';
            end
        end

        % Value changed function: optimizerSelectionCombo
        function optimizerSelectionCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.optimizerSelectionCombo;
            % hObject    handle to optimizerSelectionCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns optimizerSelectionCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from optimizerSelectionCombo
            optAlgoStrs = LvdOptimizerAlgoEnum.getListBoxStr();
            enum = LvdOptimizerAlgoEnum.getEnumForListboxStr(optAlgoStrs{get(hObject,'Value')});
            handles.optimAlgDescTextLbl.String = enum.desc;

            if(enum.reqGrad)
                handles.gradientSelCombo.Enable = 'on';
                handles.editSelectedGradAlgoOptions.Enable = 'on';
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                gradientSelCombo_Callback(app, evt);
            else
                handles.gradientSelCombo.Enable = 'off';
                handles.editSelectedGradAlgoOptions.Enable = 'off';
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %             uiresume(handles.lvd_OptimizerSelectionGUI);
            app.saveAndClose(handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_OptimizerSelectionGUI = uifigure('Visible', 'off');
            app.lvd_OptimizerSelectionGUI.Name = 'Optimizer Algorithm Selection';
            app.lvd_OptimizerSelectionGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_OptimizerSelectionGUI.Tag = 'lvd_OptimizerSelectionGUI';
            app.GridLayout = uigridlayout(app.lvd_OptimizerSelectionGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Optimizer Selection';
            app.saveAndCloseButton = uibutton(app.GridLayout, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout2 = uigridlayout(app.uipanel1);
            app.uipanel2 = uipanel(app.GridLayout2);
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout3 = uigridlayout(app.uipanel2);
            app.text3 = uilabel(app.GridLayout3);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Optimizer Algorithm';
            app.optimizerSelectionCombo = uidropdown(app.GridLayout3);
            app.optimizerSelectionCombo.Items = {'Pop-up Menu'};
            app.optimizerSelectionCombo.ValueChangedFcn = createCallbackFcn(app, @optimizerSelectionCombo_Callback, true);
            app.optimizerSelectionCombo.Tag = 'optimizerSelectionCombo';
            app.optimizerSelectionCombo.Value = 'Pop-up Menu';
            app.optimAlgDescTextLbl = uilabel(app.GridLayout3);
            app.optimAlgDescTextLbl.Tag = 'optimAlgDescTextLbl';
            app.optimAlgDescTextLbl.Text = 'Static Text';
            app.editSelectedOptimizerOptionsButton = uibutton(app.GridLayout3, 'push');
            app.editSelectedOptimizerOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @editSelectedOptimizerOptionsButton_Callback, true);
            app.editSelectedOptimizerOptionsButton.Tag = 'editSelectedOptimizerOptionsButton';
            app.editSelectedOptimizerOptionsButton.Icon = 'chart.png';
            app.editSelectedOptimizerOptionsButton.Text = 'Edit Selected Optimizer Options';
            app.uipanel3 = uipanel(app.GridLayout2);
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout4 = uigridlayout(app.uipanel3);
            app.text5 = uilabel(app.GridLayout4);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Gradient Calculation Algorithm';
            app.gradientSelCombo = uidropdown(app.GridLayout4);
            app.gradientSelCombo.Items = {'Pop-up Menu'};
            app.gradientSelCombo.ValueChangedFcn = createCallbackFcn(app, @gradientSelCombo_Callback, true);
            app.gradientSelCombo.Tag = 'gradientSelCombo';
            app.gradientSelCombo.Value = 'Pop-up Menu';
            app.optimGradAlgDescTextLbl = uilabel(app.GridLayout4);
            app.optimGradAlgDescTextLbl.Tag = 'optimGradAlgDescTextLbl';
            app.optimGradAlgDescTextLbl.Text = 'Static Text';
            app.editSelectedGradAlgoOptions = uibutton(app.GridLayout4, 'push');
            app.editSelectedGradAlgoOptions.ButtonPushedFcn = createCallbackFcn(app, @editSelectedGradAlgoOptions_Callback, true);
            app.editSelectedGradAlgoOptions.Tag = 'editSelectedGradAlgoOptions';
            app.editSelectedGradAlgoOptions.Icon = 'slope(1).png';
            app.editSelectedGradAlgoOptions.Text = 'Edit Selected Gradient Algorithm Options';
            % Show the figure after all components are created
            app.lvd_OptimizerSelectionGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_OptimizerSelectionGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_OptimizerSelectionGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_OptimizerSelectionGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_OptimizerSelectionGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_OptimizerSelectionGUI)
        end
    end
end