classdef lvd_editFminconOptionsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_editFminconOptionsGUI    matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        uipanel9                     matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        GridLayout23                 matlab.ui.container.GridLayout
        uipanel10                    matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        GridLayout4                  matlab.ui.container.GridLayout
        numParaWorkersText           matlab.ui.control.EditField
        text27                       matlab.ui.control.Label
        useParallelCombo             matlab.ui.control.DropDown
        ProblemScalingPanel          matlab.ui.container.Panel
        GridLayout24                 matlab.ui.container.GridLayout
        ScaleProblemCheckBox         matlab.ui.control.CheckBox
        GridLayout5                  matlab.ui.container.GridLayout
        GridLayout13                 matlab.ui.container.GridLayout
        uipanel8                     matlab.ui.container.Panel
        GridLayout20                 matlab.ui.container.GridLayout
        AsTolConSqpText              matlab.ui.control.EditField
        AsLineSrchBndDurText         matlab.ui.control.EditField
        AsRelLineSrchBndText         matlab.ui.control.EditField
        AsMaxSqpItersText            matlab.ui.control.EditField
        AsFuncTolText                matlab.ui.control.EditField
        text24                       matlab.ui.control.Label
        text23                       matlab.ui.control.Label
        text22                       matlab.ui.control.Label
        text21                       matlab.ui.control.Label
        text20                       matlab.ui.control.Label
        uipanel7                     matlab.ui.container.Panel
        GridLayout14                 matlab.ui.container.GridLayout
        barrierParameterUpdateCombo  matlab.ui.control.DropDown
        text17_2                     matlab.ui.control.Label
        GridLayout21                 matlab.ui.container.GridLayout
        FeasibilityModeCheckBox      matlab.ui.control.CheckBox
        text19_2                     matlab.ui.control.Label
        GridLayout19                 matlab.ui.container.GridLayout
        IpAbsProjCGTolText           matlab.ui.control.EditField
        text19                       matlab.ui.control.Label
        GridLayout18                 matlab.ui.container.GridLayout
        IpRelProjCGTolText           matlab.ui.control.EditField
        text18                       matlab.ui.control.Label
        GridLayout17                 matlab.ui.container.GridLayout
        IpMaxProjCGItersText         matlab.ui.control.EditField
        text14                       matlab.ui.control.Label
        GridLayout16                 matlab.ui.container.GridLayout
        IpInitTrustRegionRadiusText  matlab.ui.control.EditField
        text13                       matlab.ui.control.Label
        GridLayout15                 matlab.ui.container.GridLayout
        IpInitBarrierParamText       matlab.ui.control.EditField
        text12                       matlab.ui.control.Label
        IpSubproblemAlgoCombo        matlab.ui.control.DropDown
        text17                       matlab.ui.control.Label
        IpApproxAlgoCombo            matlab.ui.control.DropDown
        text16                       matlab.ui.control.Label
        GridLayout6                  matlab.ui.container.GridLayout
        ConstraintJacobianPanel      matlab.ui.container.Panel
        GridLayout22                 matlab.ui.container.GridLayout
        ComputeConstraintJacobianCheckBox  matlab.ui.control.CheckBox
        uipanel6                     matlab.ui.container.Panel
        GridLayout12                 matlab.ui.container.GridLayout
        typicalXCombo                matlab.ui.control.DropDown
        uipanel4                     matlab.ui.container.Panel
        GridLayout10                 matlab.ui.container.GridLayout
        GridLayout11                 matlab.ui.container.GridLayout
        finiteDiffStepSizeText       matlab.ui.control.EditField
        text9                        matlab.ui.control.Label
        computeOptimalStepCheckbox   matlab.ui.control.CheckBox
        finiteDiffTypeCombo          matlab.ui.control.DropDown
        uipanel3                     matlab.ui.container.Panel
        GridLayout9                  matlab.ui.container.GridLayout
        maxFuncEvalsText             matlab.ui.control.EditField
        maxItersText                 matlab.ui.control.EditField
        text7                        matlab.ui.control.Label
        text6                        matlab.ui.control.Label
        uipanel2                     matlab.ui.container.Panel
        GridLayout8                  matlab.ui.container.GridLayout
        stepTolText                  matlab.ui.control.EditField
        conTolText                   matlab.ui.control.EditField
        optTolText                   matlab.ui.control.EditField
        text5                        matlab.ui.control.Label
        text4                        matlab.ui.control.Label
        text3                        matlab.ui.control.Label
        uipanel1                     matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        algoCombo                    matlab.ui.control.DropDown
        Hyperlink                    matlab.ui.control.Hyperlink
        matlabDocLinkLabel           matlab.ui.control.Label
        text26                       matlab.ui.control.Label
        saveAndCloseButton           matlab.ui.control.Button
        text2                        matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function handles = populateGUI(app, handles, fminconOpt)
            options = fminconOpt.getOptions();

%             setDocLbl(app, handles);

            algoListboxStr = LvdFminconAlgorithmEnum.getListBoxStr();
            handles.algoCombo.String = algoListboxStr;
            handles.algoCombo.Value = LvdFminconAlgorithmEnum.getIndForName(options.algorithm.name);

            finiteDiffTypeStrs = FminconFiniteDiffTypeEnum.getListBoxStr();
            handles.finiteDiffTypeCombo.String = finiteDiffTypeStrs;
            handles.finiteDiffTypeCombo.Value = FminconFiniteDiffTypeEnum.getIndForName(options.finDiffType.name);

            typicalXStrs = OptimizerTypicalXEnum.getListBoxStr();
            handles.typicalXCombo.String = typicalXStrs;
            handles.typicalXCombo.Value = OptimizerTypicalXEnum.getIndForName(options.typicalXType.name);

            hessApproxStrs = FminconHessApproxAlgEnum.getListBoxStr();
            handles.IpApproxAlgoCombo.String = hessApproxStrs;
            handles.IpApproxAlgoCombo.Value = FminconHessApproxAlgEnum.getIndForName(options.hessianApproxAlg.name);

            subProbStrs = FminconIpSubprobAlgEnum.getListBoxStr();
            handles.IpSubproblemAlgoCombo.String = subProbStrs;
            handles.IpSubproblemAlgoCombo.Value = FminconIpSubprobAlgEnum.getIndForName(options.subproblemAlgorithm.name);

            [listboxStr, enum] = FminconBarrierParamUpdateEnum.getListBoxStr();
            app.barrierParameterUpdateCombo.Items = listboxStr;
            app.barrierParameterUpdateCombo.ItemsData = enum;
            app.barrierParameterUpdateCombo.Value = options.barrierParamUpdate;

            useParaStr = FminconUseParallelEnum.getListBoxStr();
            handles.useParallelCombo.String = useParaStr;
            handles.useParallelCombo.Value = FminconUseParallelEnum.getIndForName(options.useParallel.name);

            app.FeasibilityModeCheckBox.Value = options.feasibilityMode;

            setOptsDoubleValueStrInGUI(handles, options, 'optTol', 'optTolText');
            setOptsDoubleValueStrInGUI(handles, options, 'tolCon', 'conTolText');
            setOptsDoubleValueStrInGUI(handles, options, 'stepTol', 'stepTolText');

            setOptsDoubleValueStrInGUI(handles, options, 'maxIter', 'maxItersText');
            setOptsDoubleValueStrInGUI(handles, options, 'maxFuncEvals', 'maxFuncEvalsText');

            setOptsDoubleValueStrInGUI(handles, options, 'finDiffStepSize', 'finiteDiffStepSizeText');

            setOptsDoubleValueStrInGUI(handles, options, 'initBarrierParam', 'IpInitBarrierParamText');
            setOptsDoubleValueStrInGUI(handles, options, 'initTrustRegionRadius', 'IpInitTrustRegionRadiusText');
            setOptsDoubleValueStrInGUI(handles, options, 'maxProjCGIter', 'IpMaxProjCGItersText');
            setOptsDoubleValueStrInGUI(handles, options, 'tolProjCG', 'IpRelProjCGTolText');
            setOptsDoubleValueStrInGUI(handles, options, 'tolProjCGAbs', 'IpAbsProjCGTolText');

            setOptsDoubleValueStrInGUI(handles, options, 'funcTol', 'AsFuncTolText');
            setOptsDoubleValueStrInGUI(handles, options, 'maxSQPIter', 'AsMaxSqpItersText');
            setOptsDoubleValueStrInGUI(handles, options, 'relLineSrchBnd', 'AsRelLineSrchBndText');
            setOptsDoubleValueStrInGUI(handles, options, 'relLineSrchBndDuration', 'AsLineSrchBndDurText');
            setOptsDoubleValueStrInGUI(handles, options, 'tolConSQP', 'AsTolConSqpText');

            setOptsDoubleValueStrInGUI(handles, options, 'numWorkers', 'numParaWorkersText');

            app.ScaleProblemCheckBox.Value = options.scaleProblem;

            app.ComputeConstraintJacobianCheckBox.Value = logical(options.specifyConstraintGradient);

            handles.computeOptimalStepCheckbox.Value = double(options.computeOptimalStepSizes);
            
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            computeOptimalStepCheckbox_Callback(app, evt);
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            errMsg = validateDoubleValue(handles, errMsg, 'optTolText', 'Optimality Tolerance', 0, Inf, false);
            errMsg = validateDoubleValue(handles, errMsg, 'conTolText', 'Constraint Tolerance', 0, Inf, false);
            errMsg = validateDoubleValue(handles, errMsg, 'stepTolText', 'Step Tolerance', 0, Inf, false);

            errMsg = validateDoubleValue(handles, errMsg, 'maxItersText', 'Max Iterations', 0, Inf, true);
            errMsg = validateDoubleValue(handles, errMsg, 'maxFuncEvalsText', 'Max Function Evaluations', 0, Inf, true);

            errMsg = validateDoubleValue(handles, errMsg, 'finiteDiffStepSizeText', 'Finite Difference Step Size', 1E-10, 1E-2, false);

            errMsg = validateDoubleValue(handles, errMsg, 'IpInitBarrierParamText', 'I-P Intial Barrier Param', 1E-10, Inf, false);
            errMsg = validateDoubleValue(handles, errMsg, 'IpInitTrustRegionRadiusText', 'I-P Initial Trust Region', 1E-10, Inf, false);
            errMsg = validateDoubleValue(handles, errMsg, 'IpMaxProjCGItersText', 'I-P Max Projected C.G. Iterations', 1, Inf, true);
            errMsg = validateDoubleValue(handles, errMsg, 'IpRelProjCGTolText', 'I-P Relative Projected C.G. Tolerance', 1E-10, Inf, false);
            errMsg = validateDoubleValue(handles, errMsg, 'IpAbsProjCGTolText', 'I-P Absolute Projected C.G. Tolerance', 1E-10, Inf, false);

            errMsg = validateDoubleValue(handles, errMsg, 'AsFuncTolText', 'A-S Function Tolerance', 1E-10, 1, false);
            errMsg = validateDoubleValue(handles, errMsg, 'AsMaxSqpItersText', 'A-S Max SQP Iterations', 1, Inf, true);
            errMsg = validateDoubleValue(handles, errMsg, 'AsRelLineSrchBndText', 'A-S Relative Line Search Bound', 0, Inf, false);
            errMsg = validateDoubleValue(handles, errMsg, 'AsLineSrchBndDurText', 'A-S Line Search Bound Duration', 1, Inf, true);
            errMsg = validateDoubleValue(handles, errMsg, 'AsTolConSqpText', 'A-S Inner SQP Constraint Tolerance', 1E-10, Inf, false);

            errMsg = validateDoubleValue(handles, errMsg, 'numParaWorkersText', 'Number of Parallel Workers', 1, feature('numCores'), true);
        end


        function saveAndClose(app, handles)
            fminconOpt = getappdata(handles.lvd_editFminconOptionsGUI,'fminconOpt');
            options = fminconOpt.getOptions();

            algoListboxStr = LvdFminconAlgorithmEnum.getListBoxStr();
            options.algorithm = LvdFminconAlgorithmEnum.getEnumForListboxStr(algoListboxStr{handles.algoCombo.Value});

            finiteDiffTypeStrs = FminconFiniteDiffTypeEnum.getListBoxStr();
            options.finDiffType = FminconFiniteDiffTypeEnum.getEnumForListboxStr(finiteDiffTypeStrs{handles.finiteDiffTypeCombo.Value});

            typicalXStrs = OptimizerTypicalXEnum.getListBoxStr();
            options.typicalXType = OptimizerTypicalXEnum.getEnumForListboxStr(typicalXStrs{handles.typicalXCombo.Value});

            hessApproxStrs = FminconHessApproxAlgEnum.getListBoxStr();
            options.hessianApproxAlg = FminconHessApproxAlgEnum.getEnumForListboxStr(hessApproxStrs{handles.IpApproxAlgoCombo.Value});

            subProbStrs = FminconIpSubprobAlgEnum.getListBoxStr();
            options.subproblemAlgorithm = FminconIpSubprobAlgEnum.getEnumForListboxStr(subProbStrs{handles.IpSubproblemAlgoCombo.Value});

            options.barrierParamUpdate = app.barrierParameterUpdateCombo.Value;

            useParaStrs = FminconUseParallelEnum.getListBoxStr();
            options.useParallel = FminconUseParallelEnum.getEnumForListboxStr(useParaStrs{handles.useParallelCombo.Value});

            options.feasibilityMode = app.FeasibilityModeCheckBox.Value;

            setOptsDoubleValueInObject(handles, options, 'optTol', 'optTolText');
            setOptsDoubleValueInObject(handles, options, 'stepTol', 'stepTolText'); 
            setOptsDoubleValueInObject(handles, options, 'tolCon', 'conTolText'); 

            setOptsDoubleValueInObject(handles, options, 'maxIter', 'maxItersText');
            setOptsDoubleValueInObject(handles, options, 'maxFuncEvals', 'maxFuncEvalsText');

            setOptsDoubleValueInObject(handles, options, 'finDiffStepSize', 'finiteDiffStepSizeText');

            setOptsDoubleValueInObject(handles, options, 'initBarrierParam', 'IpInitBarrierParamText');
            setOptsDoubleValueInObject(handles, options, 'initTrustRegionRadius', 'IpInitTrustRegionRadiusText');
            setOptsDoubleValueInObject(handles, options, 'maxProjCGIter', 'IpMaxProjCGItersText');
            setOptsDoubleValueInObject(handles, options, 'tolProjCG', 'IpRelProjCGTolText');
            setOptsDoubleValueInObject(handles, options, 'tolProjCGAbs', 'IpAbsProjCGTolText');

            setOptsDoubleValueInObject(handles, options, 'funcTol', 'AsFuncTolText');
            setOptsDoubleValueInObject(handles, options, 'maxSQPIter', 'AsMaxSqpItersText');
            setOptsDoubleValueInObject(handles, options, 'relLineSrchBnd', 'AsRelLineSrchBndText');
            setOptsDoubleValueInObject(handles, options, 'relLineSrchBndDuration', 'AsLineSrchBndDurText');
            setOptsDoubleValueInObject(handles, options, 'tolConSQP', 'AsTolConSqpText');

            setOptsDoubleValueInObject(handles, options, 'numWorkers', 'numParaWorkersText');

            options.specifyConstraintGradient = app.ComputeConstraintJacobianCheckBox.Value;

            options.computeOptimalStepSizes = logical(handles.computeOptimalStepCheckbox.Value);

            options.scaleProblem = app.ScaleProblemCheckBox.Value;

            app.output.output = {true};
            close(handles.lvd_editFminconOptionsGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_editFminconOptionsGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_editFminconOptionsGUI (see VARARGIN)

            % Choose default command line output for lvd_editFminconOptionsGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            fminconOpt = varargin{1};
            setappdata(hObject,'fminconOpt',fminconOpt);
            
            app.output = varargin{2};

            handles = populateGUI(app, handles, fminconOpt);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_editFminconOptionsGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_editFminconOptionsGUI);
        end

        % Value changed function: AsFuncTolText
        function AsFuncTolText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to AsFuncTolText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of AsFuncTolText as text
            %        str2double(get(hObject,'String')) returns contents of AsFuncTolText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: AsLineSrchBndDurText
        function AsLineSrchBndDurText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to AsLineSrchBndDurText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of AsLineSrchBndDurText as text
            %        str2double(get(hObject,'String')) returns contents of AsLineSrchBndDurText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: AsMaxSqpItersText
        function AsMaxSqpItersText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to AsMaxSqpItersText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of AsMaxSqpItersText as text
            %        str2double(get(hObject,'String')) returns contents of AsMaxSqpItersText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: AsRelLineSrchBndText
        function AsRelLineSrchBndText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to AsRelLineSrchBndText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of AsRelLineSrchBndText as text
            %        str2double(get(hObject,'String')) returns contents of AsRelLineSrchBndText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: AsTolConSqpText
        function AsTolConSqpText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to AsTolConSqpText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of AsTolConSqpText as text
            %        str2double(get(hObject,'String')) returns contents of AsTolConSqpText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: IpAbsProjCGTolText
        function IpAbsProjCGTolText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to IpAbsProjCGTolText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of IpAbsProjCGTolText as text
            %        str2double(get(hObject,'String')) returns contents of IpAbsProjCGTolText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: IpInitBarrierParamText
        function IpInitBarrierParamText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to IpInitBarrierParamText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of IpInitBarrierParamText as text
            %        str2double(get(hObject,'String')) returns contents of IpInitBarrierParamText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: IpInitTrustRegionRadiusText
        function IpInitTrustRegionRadiusText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to IpInitTrustRegionRadiusText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of IpInitTrustRegionRadiusText as text
            %        str2double(get(hObject,'String')) returns contents of IpInitTrustRegionRadiusText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: IpMaxProjCGItersText
        function IpMaxProjCGItersText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to IpMaxProjCGItersText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of IpMaxProjCGItersText as text
            %        str2double(get(hObject,'String')) returns contents of IpMaxProjCGItersText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: IpRelProjCGTolText
        function IpRelProjCGTolText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to IpRelProjCGTolText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of IpRelProjCGTolText as text
            %        str2double(get(hObject,'String')) returns contents of IpRelProjCGTolText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: computeOptimalStepCheckbox
        function computeOptimalStepCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.computeOptimalStepCheckbox;
            
            % hObject    handle to computeOptimalStepCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of computeOptimalStepCheckbox
            if(get(hObject,'Value'))
                handles.finiteDiffStepSizeText.Enable = 'off';
            else
                handles.finiteDiffStepSizeText.Enable = 'on';
            end
        end

        % Value changed function: conTolText
        function conTolText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to conTolText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of conTolText as text
            %        str2double(get(hObject,'String')) returns contents of conTolText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: finiteDiffStepSizeText
        function finiteDiffStepSizeText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to finiteDiffStepSizeText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of finiteDiffStepSizeText as text
            %        str2double(get(hObject,'String')) returns contents of finiteDiffStepSizeText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: maxFuncEvalsText
        function maxFuncEvalsText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to maxFuncEvalsText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of maxFuncEvalsText as text
            %        str2double(get(hObject,'String')) returns contents of maxFuncEvalsText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: maxItersText
        function maxItersText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to maxItersText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of maxItersText as text
            %        str2double(get(hObject,'String')) returns contents of maxItersText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: numParaWorkersText
        function numParaWorkersText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to numParaWorkersText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of numParaWorkersText as text
            %        str2double(get(hObject,'String')) returns contents of numParaWorkersText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: optTolText
        function optTolText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to optTolText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of optTolText as text
            %        str2double(get(hObject,'String')) returns contents of optTolText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                app.saveAndClose(handles);
            else
                uialert(app.lvd_editFminconOptionsGUI, errMsg, 'Invalid FMINCON Options Inputs', "Icon","error");
            end
        end

        % Value changed function: stepTolText
        function stepTolText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to stepTolText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of stepTolText as text
            %        str2double(get(hObject,'String')) returns contents of stepTolText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_editFminconOptionsGUI = uifigure('Visible', 'off');
            app.lvd_editFminconOptionsGUI.Name = 'Edit FMINCON Options';
            app.lvd_editFminconOptionsGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_editFminconOptionsGUI.Tag = 'lvd_editFminconOptionsGUI';
            app.GridLayout = uigridlayout(app.lvd_editFminconOptionsGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit FMINCON Options';
            app.saveAndCloseButton = uibutton(app.GridLayout, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.uipanel9 = uipanel(app.GridLayout);
            app.uipanel9.Tag = 'uipanel9';
            app.GridLayout2 = uigridlayout(app.uipanel9);
            app.text26 = uilabel(app.GridLayout2);
            app.text26.Tag = 'text26';
            app.text26.Text = 'Leave a text field blank to use that setting''s default value.';
            app.matlabDocLinkLabel = uilabel(app.GridLayout2);
            app.matlabDocLinkLabel.Tag = 'matlabDocLinkLabel';
            app.matlabDocLinkLabel.Text = 'For more information, visit the MATLAB FMINCON documentation:';
            app.Hyperlink = uihyperlink(app.GridLayout2);
            app.Hyperlink.URL = 'https://www.mathworks.com/help/optim/ug/fmincon.html';
            app.Hyperlink.Text = 'https://www.mathworks.com/help/optim/ug/fmincon.html';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.uipanel1 = uipanel(app.GridLayout6);
            app.uipanel1.Title = 'FMINCON Algorithm';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout7 = uigridlayout(app.uipanel1);
            app.algoCombo = uidropdown(app.GridLayout7);
            app.algoCombo.Items = {'Pop-up Menu'};
            app.algoCombo.Tag = 'algoCombo';
            app.algoCombo.Tooltip = 'The optimization algorithm used by FMINCON.';
            app.algoCombo.Value = 'Pop-up Menu';
            app.uipanel2 = uipanel(app.GridLayout6);
            app.uipanel2.Title = 'Tolerances';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout8 = uigridlayout(app.uipanel2);
            app.text3 = uilabel(app.GridLayout8);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Optimality Tolerance';
            app.text4 = uilabel(app.GridLayout8);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Constraint Tolerance';
            app.text5 = uilabel(app.GridLayout8);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Step Tolerance';
            app.optTolText = uieditfield(app.GridLayout8, 'text');
            app.optTolText.ValueChangedFcn = createCallbackFcn(app, @optTolText_Callback, true);
            app.optTolText.Tag = 'optTolText';
            app.optTolText.Tooltip = 'Termination tolerance on the first-order optimality, a positive scalar. The default is 1e-6.';
            app.optTolText.Value = '0.0';
            app.conTolText = uieditfield(app.GridLayout8, 'text');
            app.conTolText.ValueChangedFcn = createCallbackFcn(app, @conTolText_Callback, true);
            app.conTolText.Tag = 'conTolText';
            app.conTolText.Tooltip = 'Tolerance on the constraint violation, a positive scalar. The default is 1e-6. ';
            app.conTolText.Value = '0.0';
            app.stepTolText = uieditfield(app.GridLayout8, 'text');
            app.stepTolText.ValueChangedFcn = createCallbackFcn(app, @stepTolText_Callback, true);
            app.stepTolText.Tag = 'stepTolText';
            app.stepTolText.Tooltip = 'Termination tolerance on x, a positive scalar. The default value for all algorithms except ''interior-point'' is 1e-6; for the ''interior-point'' algorithm, the default is 1e-10.';
            app.stepTolText.Value = '0.0';
            app.uipanel3 = uipanel(app.GridLayout6);
            app.uipanel3.Title = 'Maximums (Termination Conditions)';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout9 = uigridlayout(app.uipanel3);
            app.text6 = uilabel(app.GridLayout9);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Max Iterations';
            app.text7 = uilabel(app.GridLayout9);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Max Function Evals';
            app.maxItersText = uieditfield(app.GridLayout9, 'text');
            app.maxItersText.ValueChangedFcn = createCallbackFcn(app, @maxItersText_Callback, true);
            app.maxItersText.Tag = 'maxItersText';
            app.maxItersText.Tooltip = 'Maximum number of iterations allowed, a positive integer. The default value for all algorithms except interior-point is 400; for the interior-point algorithm the default is 1000.';
            app.maxItersText.Value = '0.0';
            app.maxFuncEvalsText = uieditfield(app.GridLayout9, 'text');
            app.maxFuncEvalsText.ValueChangedFcn = createCallbackFcn(app, @maxFuncEvalsText_Callback, true);
            app.maxFuncEvalsText.Tag = 'maxFuncEvalsText';
            app.maxFuncEvalsText.Tooltip = 'Maximum number of function evaluations allowed, a positive integer. The default value for all algorithms except interior-point is 100*numberOfVariables; for the interior-point algorithm the default is 3000.';
            app.maxFuncEvalsText.Value = '0.0';
            app.uipanel4 = uipanel(app.GridLayout6);
            app.uipanel4.Title = 'Built-In Finite Differences';
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout10 = uigridlayout(app.uipanel4);
            app.finiteDiffTypeCombo = uidropdown(app.GridLayout10);
            app.finiteDiffTypeCombo.Items = {'Pop-up Menu'};
            app.finiteDiffTypeCombo.Tag = 'finiteDiffTypeCombo';
            app.finiteDiffTypeCombo.Tooltip = 'Finite differences, used to estimate gradients, are either ''forward'' (default), or ''central'' (centered). ''central'' takes twice as many function evaluations but should be more accurate.';
            app.finiteDiffTypeCombo.Value = 'Pop-up Menu';
            app.computeOptimalStepCheckbox = uicheckbox(app.GridLayout10);
            app.computeOptimalStepCheckbox.ValueChangedFcn = createCallbackFcn(app, @computeOptimalStepCheckbox_Callback, true);
            app.computeOptimalStepCheckbox.Tag = 'computeOptimalStepCheckbox';
            app.computeOptimalStepCheckbox.Tooltip = 'If checked, the optimizer ignores the fixed step size value and instead determines the optimal step size at the cost of (potentially a lot of) CPU usage.';
            app.computeOptimalStepCheckbox.Text = 'Compute Optimal Step Size';
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.text9 = uilabel(app.GridLayout11);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Step Size';
            app.finiteDiffStepSizeText = uieditfield(app.GridLayout11, 'text');
            app.finiteDiffStepSizeText.ValueChangedFcn = createCallbackFcn(app, @finiteDiffStepSizeText_Callback, true);
            app.finiteDiffStepSizeText.Tag = 'finiteDiffStepSizeText';
            app.finiteDiffStepSizeText.Tooltip = 'Step size factor for built-in finite differences.  The default is sqrt(eps) for forward finite differences, and eps^(1/3) for central finite differences.';
            app.finiteDiffStepSizeText.Value = '0.0';
            app.uipanel6 = uipanel(app.GridLayout6);
            app.uipanel6.Title = 'Typical Variable Values';
            app.uipanel6.Tag = 'uipanel6';
            app.GridLayout12 = uigridlayout(app.uipanel6);
            app.typicalXCombo = uidropdown(app.GridLayout12);
            app.typicalXCombo.Items = {'Pop-up Menu'};
            app.typicalXCombo.Tag = 'typicalXCombo';
            app.typicalXCombo.Tooltip = 'Use either the initial values as "typical" representations of the variable values or use a vector of ones instead.  Use ones when you are far away from the optimized solution and initial values when you are close.';
            app.typicalXCombo.Value = 'Pop-up Menu';
            app.ConstraintJacobianPanel = uipanel(app.GridLayout6);
            app.ConstraintJacobianPanel.Title = 'Constraint Jacobian';
            app.GridLayout22 = uigridlayout(app.ConstraintJacobianPanel);
            app.ComputeConstraintJacobianCheckBox = uicheckbox(app.GridLayout22);
            app.ComputeConstraintJacobianCheckBox.Tooltip = {'When enabled, FMINCON will compute the Jacobian (matrix of first partials) of the nonlinear constraint functions at each iteration.  This may improve optimizer performance at the cost of additional CPU time and calculations needed.'};
            app.ComputeConstraintJacobianCheckBox.Text = 'Compute Constraint Jacobian';
            app.GridLayout13 = uigridlayout(app.GridLayout5);
            app.uipanel7 = uipanel(app.GridLayout13);
            app.uipanel7.Title = 'Interior Point Algorithm Options';
            app.uipanel7.Tag = 'uipanel7';
            app.GridLayout14 = uigridlayout(app.uipanel7);
            app.text16 = uilabel(app.GridLayout14);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Hessian Approximation Algorithm';
            app.IpApproxAlgoCombo = uidropdown(app.GridLayout14);
            app.IpApproxAlgoCombo.Items = {'Pop-up Menu'};
            app.IpApproxAlgoCombo.Tag = 'IpApproxAlgoCombo';
            app.IpApproxAlgoCombo.Tooltip = 'How fmincon calculates the Hessian of the objective function.';
            app.IpApproxAlgoCombo.Value = 'Pop-up Menu';
            app.text17 = uilabel(app.GridLayout14);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Subproblem Algorithm';
            app.IpSubproblemAlgoCombo = uidropdown(app.GridLayout14);
            app.IpSubproblemAlgoCombo.Items = {'Pop-up Menu'};
            app.IpSubproblemAlgoCombo.Tag = 'IpSubproblemAlgoCombo';
            app.IpSubproblemAlgoCombo.Tooltip = 'Determines how the iteration step is calculated. The default, ''factorization'', is usually faster than ''cg'' (conjugate gradient), though ''cg'' might be faster for large problems with dense Hessians.';
            app.IpSubproblemAlgoCombo.Value = 'Pop-up Menu';
            app.GridLayout15 = uigridlayout(app.GridLayout14);
            app.text12 = uilabel(app.GridLayout15);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Initial Barrier Param';
            app.IpInitBarrierParamText = uieditfield(app.GridLayout15, 'text');
            app.IpInitBarrierParamText.ValueChangedFcn = createCallbackFcn(app, @IpInitBarrierParamText_Callback, true);
            app.IpInitBarrierParamText.Tag = 'IpInitBarrierParamText';
            app.IpInitBarrierParamText.Tooltip = 'Initial barrier value, a positive scalar. Sometimes it might help to try a value above the default 0.1, especially if the objective or constraint functions are large';
            app.IpInitBarrierParamText.Value = '0.0';
            app.GridLayout16 = uigridlayout(app.GridLayout14);
            app.text13 = uilabel(app.GridLayout16);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Init Trust Region Rad.';
            app.IpInitTrustRegionRadiusText = uieditfield(app.GridLayout16, 'text');
            app.IpInitTrustRegionRadiusText.ValueChangedFcn = createCallbackFcn(app, @IpInitTrustRegionRadiusText_Callback, true);
            app.IpInitTrustRegionRadiusText.Tag = 'IpInitTrustRegionRadiusText';
            app.IpInitTrustRegionRadiusText.Tooltip = 'Initial radius of the trust region, a positive scalar. On badly scaled problems it might help to choose a value smaller than the default sqrt(n) , where n is the number of variables.';
            app.IpInitTrustRegionRadiusText.Value = '0.0';
            app.GridLayout17 = uigridlayout(app.GridLayout14);
            app.text14 = uilabel(app.GridLayout17);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Max. Proj. CG Iters';
            app.IpMaxProjCGItersText = uieditfield(app.GridLayout17, 'text');
            app.IpMaxProjCGItersText.ValueChangedFcn = createCallbackFcn(app, @IpMaxProjCGItersText_Callback, true);
            app.IpMaxProjCGItersText.Tag = 'IpMaxProjCGItersText';
            app.IpMaxProjCGItersText.Tooltip = 'A tolerance (stopping criterion) for the number of projected conjugate gradient iterations; this is an inner iteration, not the number of iterations of the algorithm. This positive integer has a default value of 2*(numberOfVariables - numberOfEqualities).';
            app.IpMaxProjCGItersText.Value = '0.0';
            app.GridLayout18 = uigridlayout(app.GridLayout14);
            app.text18 = uilabel(app.GridLayout18);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Rel Proj. CG Tol.';
            app.IpRelProjCGTolText = uieditfield(app.GridLayout18, 'text');
            app.IpRelProjCGTolText.ValueChangedFcn = createCallbackFcn(app, @IpRelProjCGTolText_Callback, true);
            app.IpRelProjCGTolText.Tag = 'IpRelProjCGTolText';
            app.IpRelProjCGTolText.Tooltip = 'A relative tolerance (stopping criterion) for projected conjugate gradient algorithm; this is for an inner iteration, not the algorithm iteration. This positive scalar has a default of 0.01.';
            app.IpRelProjCGTolText.Value = '0.0';
            app.GridLayout19 = uigridlayout(app.GridLayout14);
            app.text19 = uilabel(app.GridLayout19);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Abs. Proj. CG Tol.';
            app.IpAbsProjCGTolText = uieditfield(app.GridLayout19, 'text');
            app.IpAbsProjCGTolText.ValueChangedFcn = createCallbackFcn(app, @IpAbsProjCGTolText_Callback, true);
            app.IpAbsProjCGTolText.Tag = 'IpAbsProjCGTolText';
            app.IpAbsProjCGTolText.Tooltip = 'Absolute tolerance (stopping criterion) for projected conjugate gradient algorithm; this is for an inner iteration, not the algorithm iteration. This positive scalar has a default of 1e-10.';
            app.IpAbsProjCGTolText.Value = '0.0';
            app.GridLayout21 = uigridlayout(app.GridLayout14);
            app.text19_2 = uilabel(app.GridLayout21);
            app.text19_2.Tag = 'text19';
            app.text19_2.Tooltip = {'When true, fmincon uses a different algorithm for achieving feasibility. This setting can help fmincon reach a feasible solution in some cases. The default value is false.  Feasibility mode usually performs better when SubproblemAlgorithm is ''cg''.'};
            app.text19_2.Text = 'Feasibility Mode';
            app.FeasibilityModeCheckBox = uicheckbox(app.GridLayout21);
            app.FeasibilityModeCheckBox.Tooltip = {'When true, fmincon uses a different algorithm for achieving feasibility. This setting can help fmincon reach a feasible solution in some cases. The default value is false.  Feasibility mode usually performs better when SubproblemAlgorithm is ''cg''.'};
            app.FeasibilityModeCheckBox.Text = '';
            app.text17_2 = uilabel(app.GridLayout14);
            app.text17_2.Tag = 'text17';
            app.text17_2.Text = 'Barrier Parameter Update Algorithm';
            app.barrierParameterUpdateCombo = uidropdown(app.GridLayout14);
            app.barrierParameterUpdateCombo.Items = {'Pop-up Menu'};
            app.barrierParameterUpdateCombo.Tag = 'barrierParameterUpdateCombo';
            app.barrierParameterUpdateCombo.Tooltip = '''Specifies how fmincon updates the barrier parameter.  This option can affect the speed and convergence of the solver, but the effect is not easy to predict.''';
            app.barrierParameterUpdateCombo.Value = 'Pop-up Menu';
            app.uipanel8 = uipanel(app.GridLayout13);
            app.uipanel8.Title = 'Active Set Algorithm Options';
            app.uipanel8.Tag = 'uipanel8';
            app.GridLayout20 = uigridlayout(app.uipanel8);
            app.text20 = uilabel(app.GridLayout20);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Function Tolerance';
            app.text21 = uilabel(app.GridLayout20);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Max SQP Iterations';
            app.text22 = uilabel(app.GridLayout20);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Rel. Line Srch. Bnd.';
            app.text23 = uilabel(app.GridLayout20);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Line Srch. Bnd. Dur.';
            app.text24 = uilabel(app.GridLayout20);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Inner SQP Constr Tol.';
            app.AsFuncTolText = uieditfield(app.GridLayout20, 'text');
            app.AsFuncTolText.ValueChangedFcn = createCallbackFcn(app, @AsFuncTolText_Callback, true);
            app.AsFuncTolText.Tag = 'AsFuncTolText';
            app.AsFuncTolText.Tooltip = 'Termination tolerance on the function value, a positive scalar. The default is 1e-6.';
            app.AsFuncTolText.Value = '0.0';
            app.AsMaxSqpItersText = uieditfield(app.GridLayout20, 'text');
            app.AsMaxSqpItersText.ValueChangedFcn = createCallbackFcn(app, @AsMaxSqpItersText_Callback, true);
            app.AsMaxSqpItersText.Tag = 'AsMaxSqpItersText';
            app.AsMaxSqpItersText.Tooltip = 'Maximum number of SQP iterations allowed, a positive integer. The default is 10*max(numberOfVariables, numberOfInequalities + numberOfBounds).';
            app.AsMaxSqpItersText.Value = '0.0';
            app.AsRelLineSrchBndText = uieditfield(app.GridLayout20, 'text');
            app.AsRelLineSrchBndText.ValueChangedFcn = createCallbackFcn(app, @AsRelLineSrchBndText_Callback, true);
            app.AsRelLineSrchBndText.Tag = 'AsRelLineSrchBndText';
            app.AsRelLineSrchBndText.Tooltip = 'Relative bound (a real nonnegative scalar value) on the line search step length. The total displacement in x satisfies |Δx(i)| ≤ relLineSrchBnd· max(|x(i)|,|typicalx(i)|). This option provides control over the magnitude of the displacements in x for cases in which the solver takes steps that are considered too large. The default is no bounds.';
            app.AsRelLineSrchBndText.Value = '0.0';
            app.AsLineSrchBndDurText = uieditfield(app.GridLayout20, 'text');
            app.AsLineSrchBndDurText.ValueChangedFcn = createCallbackFcn(app, @AsLineSrchBndDurText_Callback, true);
            app.AsLineSrchBndDurText.Tag = 'AsLineSrchBndDurText';
            app.AsLineSrchBndDurText.Tooltip = 'Number of iterations for which the bound specified in RelLineSrchBnd should be active (default is 1).';
            app.AsLineSrchBndDurText.Value = '0.0';
            app.AsTolConSqpText = uieditfield(app.GridLayout20, 'text');
            app.AsTolConSqpText.ValueChangedFcn = createCallbackFcn(app, @AsTolConSqpText_Callback, true);
            app.AsTolConSqpText.Tag = 'AsTolConSqpText';
            app.AsTolConSqpText.Tooltip = 'Termination tolerance on inner iteration SQP constraint violation, a positive scalar. The default is 1e-6.';
            app.AsTolConSqpText.Value = '0.0';
            app.GridLayout23 = uigridlayout(app.GridLayout2);
            app.ProblemScalingPanel = uipanel(app.GridLayout23);
            app.ProblemScalingPanel.Title = 'Problem Scaling';
            app.GridLayout24 = uigridlayout(app.ProblemScalingPanel);
            app.ScaleProblemCheckBox = uicheckbox(app.GridLayout24);
            app.ScaleProblemCheckBox.Tooltip = {'true causes the algorithm to normalize all constraints and the objective function. Disable by setting to the default false.'};
            app.ScaleProblemCheckBox.Text = 'Scale Problem';
            app.uipanel10 = uipanel(app.GridLayout23);
            app.uipanel10.Title = 'Parallelization';
            app.uipanel10.Tag = 'uipanel10';
            app.GridLayout3 = uigridlayout(app.uipanel10);
            app.useParallelCombo = uidropdown(app.GridLayout3);
            app.useParallelCombo.Items = {'Pop-up Menu'};
            app.useParallelCombo.Tag = 'useParallelCombo';
            app.useParallelCombo.Tooltip = 'When selected, fmincon estimates gradients in parallel.  Parallel calculation of gradients can speed up the optimization.';
            app.useParallelCombo.Value = 'Pop-up Menu';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.text27 = uilabel(app.GridLayout4);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Num. Parallel Workers';
            app.numParaWorkersText = uieditfield(app.GridLayout4, 'text');
            app.numParaWorkersText.ValueChangedFcn = createCallbackFcn(app, @numParaWorkersText_Callback, true);
            app.numParaWorkersText.Tag = 'numParaWorkersText';
            app.numParaWorkersText.Tooltip = 'The number of parallel workers to use for computing the gradients.';
            app.numParaWorkersText.Value = '0.0';
            % Show the figure after all components are created
            app.lvd_editFminconOptionsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_editFminconOptionsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_editFminconOptionsGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_editFminconOptionsGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_editFminconOptionsGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_editFminconOptionsGUI)
        end
    end
end