classdef lvd_editIpoptOptionsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_editIpoptOptionsGUI  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        uipanel1                 matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        GridLayout3              matlab.ui.container.GridLayout
        GridLayout8              matlab.ui.container.GridLayout
        uipanel4                 matlab.ui.container.Panel
        GridLayout10             matlab.ui.container.GridLayout
        GridLayout11             matlab.ui.container.GridLayout
        numParaWorkersText       matlab.ui.control.EditField
        text16                   matlab.ui.control.Label
        useParallelCombo         matlab.ui.control.DropDown
        uipanel2                 matlab.ui.container.Panel
        GridLayout9              matlab.ui.container.GridLayout
        maxCpuTime               matlab.ui.control.EditField
        maxIterText              matlab.ui.control.EditField
        tolText                  matlab.ui.control.EditField
        constrViolTolText        matlab.ui.control.EditField
        dualInfTolText           matlab.ui.control.EditField
        text6                    matlab.ui.control.Label
        text7                    matlab.ui.control.Label
        tolLabel                 matlab.ui.control.Label
        text4                    matlab.ui.control.Label
        text5                    matlab.ui.control.Label
        uipanel3                 matlab.ui.container.Panel
        GridLayout4              matlab.ui.container.GridLayout
        GridLayout7              matlab.ui.container.GridLayout
        nuIncrText               matlab.ui.control.EditField
        text13                   matlab.ui.control.Label
        GridLayout6              matlab.ui.container.GridLayout
        nuInitText               matlab.ui.control.EditField
        text12                   matlab.ui.control.Label
        GridLayout5              matlab.ui.container.GridLayout
        alphaRedFactorText       matlab.ui.control.EditField
        text9                    matlab.ui.control.Label
        correctorTypeCombo       matlab.ui.control.DropDown
        text10                   matlab.ui.control.Label
        recalcYCombo             matlab.ui.control.DropDown
        text11                   matlab.ui.control.Label
        alphaForYCombo           matlab.ui.control.DropDown
        text8                    matlab.ui.control.Label
        Hyperlink                matlab.ui.control.Hyperlink
        text15                   matlab.ui.control.Label
        docLinkLabel             matlab.ui.control.Label
        saveAndCloseButton       matlab.ui.control.Button
        text2                    matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function handles = populateGUI(app, handles, ipoptOpt)
            options = ipoptOpt.getOptions();

            %                 setDocLbl(app, handles);

            listboxStrs = IpOptAlphaForYEnum.getListBoxStr();
            handles.alphaForYCombo.String = listboxStrs;
            handles.alphaForYCombo.Value = IpOptAlphaForYEnum.getIndForName(options.alpha_for_y.name);

            listboxStrs = IpOptCorrectorTypeEnum.getListBoxStr();
            handles.correctorTypeCombo.String = listboxStrs;
            handles.correctorTypeCombo.Value = IpOptCorrectorTypeEnum.getIndForName(options.corrector_type.name);

            listboxStrs = IpOptRecalcYEnum.getListBoxStr();
            handles.recalcYCombo.String = listboxStrs;
            handles.recalcYCombo.Value = IpOptRecalcYEnum.getIndForName(options.recalc_y.name);

            listboxStrs = IpoptUseParallelEnum.getListBoxStr();
            handles.useParallelCombo.String = listboxStrs;
            handles.useParallelCombo.Value = IpoptUseParallelEnum.getIndForName(options.useParallel.name);

            setOptsDoubleValueStrInGUI(handles, options, 'dual_inf_tol', 'dualInfTolText');
            setOptsDoubleValueStrInGUI(handles, options, 'constr_viol_tol', 'constrViolTolText');
            setOptsDoubleValueStrInGUI(handles, options, 'tol', 'tolText');
            setOptsDoubleValueStrInGUI(handles, options, 'max_iter', 'maxIterText');
            setOptsDoubleValueStrInGUI(handles, options, 'max_cpu_time', 'maxCpuTime');

            setOptsDoubleValueStrInGUI(handles, options, 'alpha_red_factor', 'alphaRedFactorText');
            setOptsDoubleValueStrInGUI(handles, options, 'nu_inc', 'nuIncrText');
            setOptsDoubleValueStrInGUI(handles, options, 'nu_init', 'nuInitText');

            setOptsDoubleValueStrInGUI(handles, options, 'numWorkers', 'numParaWorkersText');
        end

        %         function setDocLbl(app, handles)
        %                 docLinkLblPos = handles.docLinkLabel.Position;
        %                 docLinkLblParent = handles.docLinkLabel.Parent;
        %                 handles.docLinkLabel.Visible = 'off';
        %
        %                 % Create and display the text label
        %                 fileName = 'IPOPT_Doc.pdf';
        %                 labelStr = ['<html><div style=''text-align: center;''>For more information, open the documentation PDF for IPOPT: <a href="">' fileName '</a></div></html>'];
        %                 jLabel = javaObjectEDT('javax.swing.JLabel', labelStr);
        %                 [hjLabel,~] = javacomponent(jLabel, docLinkLblPos, docLinkLblParent);
        %
        %                 % Modify the mouse cursor when hovering on the label
        %                 hjLabel.setCursor(java.awt.Cursor.getPredefinedCursor(java.awt.Cursor.HAND_CURSOR));
        %
        %                 % Set the mouse-click callback
        %                 set(hjLabel, 'MouseClickedCallback', @(h,e)open(['.\helper_methods\math\ipopt\',fileName]));
        %         end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            errMsg = validateDoubleValue(handles, errMsg, 'dualInfTolText', 'Optimality Tolerance', 1E-12, Inf, false);
            errMsg = validateDoubleValue(handles, errMsg, 'constrViolTolText', 'Constraint Tolerance', 1E-12, Inf, false);
            errMsg = validateDoubleValue(handles, errMsg, 'tolText', 'Tolerance Tolerance', 1E-12, Inf, false);
            errMsg = validateDoubleValue(handles, errMsg, 'maxIterText', 'Maximum Iterations', 0, Inf, true);
            errMsg = validateDoubleValue(handles, errMsg, 'maxCpuTime', 'Max CPU Time', 0, Inf, false);

            errMsg = validateDoubleValue(handles, errMsg, 'alphaRedFactorText', 'Alpha Red Factor', 0, 1, false);
            errMsg = validateDoubleValue(handles, errMsg, 'nuIncrText', 'Nu Increment', 1E-12, Inf, false);
            errMsg = validateDoubleValue(handles, errMsg, 'nuInitText', 'Initial Nu', 1E-12, Inf, false);

            errMsg = validateDoubleValue(handles, errMsg, 'numParaWorkersText', 'Number of Parallel Workers', 1, feature('numCores'), true);
        end


        function saveAndClose(app, handles)
            ipoptOpt = getappdata(handles.lvd_editIpoptOptionsGUI,'ipoptOpt');
            options = ipoptOpt.getOptions();

            listboxStrs = IpOptAlphaForYEnum.getListBoxStr();
            options.alpha_for_y = IpOptAlphaForYEnum.getEnumForListboxStr(listboxStrs{handles.alphaForYCombo.Value});

            listboxStrs = IpOptCorrectorTypeEnum.getListBoxStr();
            options.corrector_type = IpOptCorrectorTypeEnum.getEnumForListboxStr(listboxStrs{handles.correctorTypeCombo.Value});

            listboxStrs = IpOptRecalcYEnum.getListBoxStr();
            options.recalc_y = IpOptRecalcYEnum.getEnumForListboxStr(listboxStrs{handles.recalcYCombo.Value});

            listboxStrs = IpoptUseParallelEnum.getListBoxStr();
            options.useParallel = IpoptUseParallelEnum.getEnumForListboxStr(listboxStrs{handles.useParallelCombo.Value});

            setOptsDoubleValueInObject(handles, options, 'dual_inf_tol', 'dualInfTolText');
            setOptsDoubleValueInObject(handles, options, 'constr_viol_tol', 'constrViolTolText');
            setOptsDoubleValueInObject(handles, options, 'tol', 'tolText');
            setOptsDoubleValueInObject(handles, options, 'max_iter', 'maxIterText');
            setOptsDoubleValueInObject(handles, options, 'max_cpu_time', 'maxCpuTime');

            setOptsDoubleValueInObject(handles, options, 'alpha_red_factor', 'alphaRedFactorText');
            setOptsDoubleValueInObject(handles, options, 'nu_inc', 'nuIncrText');
            setOptsDoubleValueInObject(handles, options, 'nu_init', 'nuInitText');

            setOptsDoubleValueInObject(handles, options, 'numWorkers', 'numParaWorkersText');

            app.output.output = {true};
            close(handles.lvd_editIpoptOptionsGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_editIpoptOptionsGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_editIpoptOptionsGUI (see VARARGIN)

            % Choose default command line output for lvd_editIpoptOptionsGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            ipoptOpt = varargin{1};
            setappdata(hObject,'ipoptOpt',ipoptOpt);
            
            app.output = varargin{2};

            handles = populateGUI(app, handles, ipoptOpt);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_editIpoptOptionsGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_editIpoptOptionsGUI);
        end

        % Value changed function: alphaRedFactorText
        function alphaRedFactorText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to alphaRedFactorText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of alphaRedFactorText as text
            %        str2double(get(hObject,'String')) returns contents of alphaRedFactorText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: constrViolTolText
        function constrViolTolText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to constrViolTolText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of constrViolTolText as text
            %        str2double(get(hObject,'String')) returns contents of constrViolTolText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: dualInfTolText
        function dualInfTolText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to dualInfTolText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of dualInfTolText as text
            %        str2double(get(hObject,'String')) returns contents of dualInfTolText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: maxCpuTime
        function maxCpuTime_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to maxCpuTime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of maxCpuTime as text
            %        str2double(get(hObject,'String')) returns contents of maxCpuTime as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: maxIterText
        function maxIterText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to maxIterText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of maxIterText as text
            %        str2double(get(hObject,'String')) returns contents of maxIterText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: nuIncrText
        function nuIncrText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to nuIncrText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of nuIncrText as text
            %        str2double(get(hObject,'String')) returns contents of nuIncrText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: nuInitText
        function nuInitText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to nuInitText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of nuInitText as text
            %        str2double(get(hObject,'String')) returns contents of nuInitText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: numParaWorkersText
        function numParaWorkersText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to numParaWorkersText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of numParaWorkersText as text
            %        str2double(get(hObject,'String')) returns contents of numParaWorkersText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
%                 uiresume(handles.lvd_editIpoptOptionsGUI);
                app.saveAndClose(handles);
            else
%                 msgbox(errMsg,'Invalid IPOPT Options Inputs','error');
                uialert(app.lvd_editIpoptOptionsGUI, errMsg, 'Invalid IPOPT Options Inputs', "Icon","error");
            end
        end

        % Value changed function: tolText
        function tolText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to tolText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of tolText as text
            %        str2double(get(hObject,'String')) returns contents of tolText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_editIpoptOptionsGUI = uifigure('Visible', 'off');
            app.lvd_editIpoptOptionsGUI.Name = 'Edit IPOPT Options';
            app.lvd_editIpoptOptionsGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_editIpoptOptionsGUI.Tag = 'lvd_editIpoptOptionsGUI';
            app.GridLayout = uigridlayout(app.lvd_editIpoptOptionsGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit IPOPT Options';
            app.saveAndCloseButton = uibutton(app.GridLayout, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout2 = uigridlayout(app.uipanel1);
            app.docLinkLabel = uilabel(app.GridLayout2);
            app.docLinkLabel.Tag = 'docLinkLabel';
            app.docLinkLabel.Text = 'For more information, visit the IPOPT documentation:';
            app.text15 = uilabel(app.GridLayout2);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Leave a text field blank to use that setting''s default value.';
            app.Hyperlink = uihyperlink(app.GridLayout2);
            app.Hyperlink.URL = 'IPOPT_Doc.pdf';
            app.Hyperlink.Text = 'IPOPT_Doc.pdf';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.uipanel3 = uipanel(app.GridLayout3);
            app.uipanel3.Title = 'Line Search';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout4 = uigridlayout(app.uipanel3);
            app.text8 = uilabel(app.GridLayout4);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Alpha For Y';
            app.alphaForYCombo = uidropdown(app.GridLayout4);
            app.alphaForYCombo.Items = {'Pop-up Menu'};
            app.alphaForYCombo.Tag = 'alphaForYCombo';
            app.alphaForYCombo.Tooltip = 'Method to determine the step size for constraint multipliers. This option determines how the step size (alpha_y) will be calculated when updating the constraint multipliers. The default value for this string option is ”primal”. ';
            app.alphaForYCombo.Value = 'Pop-up Menu';
            app.text11 = uilabel(app.GridLayout4);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Recalculate Y?';
            app.recalcYCombo = uidropdown(app.GridLayout4);
            app.recalcYCombo.Items = {'Pop-up Menu'};
            app.recalcYCombo.Tag = 'recalcYCombo';
            app.recalcYCombo.Tooltip = 'Tells the algorithm to recalculate the equality and inequality multipliers as least square estimates. This asks the algorithm to recompute the multipliers, whenever the current infeasibility is less than recalc_y_feas_tol. Choosing yes might be helpful in the quasi-Newton option. However, each recalculation requires an extra factorization of the linear system. If a limited memory quasi-Newton option is chosen, this is used by default. The default value for this string option is ”no”. ';
            app.recalcYCombo.Value = 'Pop-up Menu';
            app.text10 = uilabel(app.GridLayout4);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Corrector Type';
            app.correctorTypeCombo = uidropdown(app.GridLayout4);
            app.correctorTypeCombo.Items = {'Pop-up Menu'};
            app.correctorTypeCombo.Tag = 'correctorTypeCombo';
            app.correctorTypeCombo.Tooltip = 'The type of corrector steps that should be taken (unsupported!). If ”mu_strategy” is ”adaptive”, this option determines what kind of corrector steps should be tried. The default value for this string option is ”none”. ';
            app.correctorTypeCombo.Value = 'Pop-up Menu';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.text9 = uilabel(app.GridLayout5);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Alpha Red Factor';
            app.alphaRedFactorText = uieditfield(app.GridLayout5, 'text');
            app.alphaRedFactorText.ValueChangedFcn = createCallbackFcn(app, @alphaRedFactorText_Callback, true);
            app.alphaRedFactorText.Tag = 'alphaRedFactorText';
            app.alphaRedFactorText.Tooltip = 'Fractional reduction of the trial step size in the backtracking line search. At every step of the backtracking line search, the trial step size is reduced by this factor. The valid range for this real option is 0 < alpha_red_factor < 1 and its default value is 0.5. ';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.text12 = uilabel(app.GridLayout6);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Initial Nu';
            app.nuInitText = uieditfield(app.GridLayout6, 'text');
            app.nuInitText.ValueChangedFcn = createCallbackFcn(app, @nuInitText_Callback, true);
            app.nuInitText.Tag = 'nuInitText';
            app.nuInitText.Tooltip = ' Initial value of the penalty parameter. The valid range for this real option is 0 < nu_init < +inf and its default value is 1E6. ';
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.text13 = uilabel(app.GridLayout7);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Nu Increment';
            app.nuIncrText = uieditfield(app.GridLayout7, 'text');
            app.nuIncrText.ValueChangedFcn = createCallbackFcn(app, @nuIncrText_Callback, true);
            app.nuIncrText.Tag = 'nuIncrText';
            app.nuIncrText.Tooltip = ' Increment of the penalty parameter. The valid range for this real option is 0 < nu_inc < +inf and its default value is 0.0001. ';
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.uipanel2 = uipanel(app.GridLayout8);
            app.uipanel2.Title = 'Convergence';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout9 = uigridlayout(app.uipanel2);
            app.text5 = uilabel(app.GridLayout9);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Optimality Tolerance';
            app.text4 = uilabel(app.GridLayout9);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Constraint Tolerance';
            app.tolLabel = uilabel(app.GridLayout9);
            app.tolLabel.Tag = 'tolLabel';
            app.tolLabel.Text = 'Tolerance';
            app.text7 = uilabel(app.GridLayout9);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Maximum Iterations';
            app.text6 = uilabel(app.GridLayout9);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Max CPU Time';
            app.dualInfTolText = uieditfield(app.GridLayout9, 'text');
            app.dualInfTolText.ValueChangedFcn = createCallbackFcn(app, @dualInfTolText_Callback, true);
            app.dualInfTolText.Tag = 'dualInfTolText';
            app.dualInfTolText.Tooltip = 'Absolute tolerance on the dual infeasibility. Successful termination requires that the max-norm of the (unscaled) dual infeasibility is less than this threshold. The valid range for this real option is 0 < dual_inf_tol < +inf and its default value is 1. ';
            app.constrViolTolText = uieditfield(app.GridLayout9, 'text');
            app.constrViolTolText.ValueChangedFcn = createCallbackFcn(app, @constrViolTolText_Callback, true);
            app.constrViolTolText.Tag = 'constrViolTolText';
            app.constrViolTolText.Tooltip = 'Absolute tolerance on the constraint violation. Successful termination requires that the max-norm of the (unscaled) constraint violation is less than this threshold. The valid range for this real option is 0 < constr_viol_tol < +inf and its default value is 0.0001. ';
            app.tolText = uieditfield(app.GridLayout9, 'text');
            app.tolText.ValueChangedFcn = createCallbackFcn(app, @tolText_Callback, true);
            app.tolText.Tag = 'tolText';
            app.tolText.Tooltip = 'Determines the convergence tolerance for the algorithm. The algorithm terminates successfully, if the (scaled) NLP error becomes smaller than this value, and if the (absolute) criteria according to ”dual_inf_tol”, ”primal_inf_tol”, and ”compl_inf_tol” are met. (This is epsilon_tol in Eqn. (6) in implementation paper). See also ”acceptable_tol” as a second termination criterion. Note, some other algorithmic features also use this quantity to determine thresholds etc. The valid range for this real option is 0 < tol < +inf and its default value is 1E-8. ';
            app.maxIterText = uieditfield(app.GridLayout9, 'text');
            app.maxIterText.ValueChangedFcn = createCallbackFcn(app, @maxIterText_Callback, true);
            app.maxIterText.Tag = 'maxIterText';
            app.maxIterText.Tooltip = 'The algorithm terminates with an error message if the number of iterations exceeded this number. The valid range for this integer option is 0 ≤ max_iter < +inf and its default value is 3000.';
            app.maxCpuTime = uieditfield(app.GridLayout9, 'text');
            app.maxCpuTime.ValueChangedFcn = createCallbackFcn(app, @maxCpuTime_Callback, true);
            app.maxCpuTime.Tag = 'maxCpuTime';
            app.maxCpuTime.Tooltip = 'max_cpu_time: Maximum number of CPU seconds. A limit on CPU seconds that Ipopt can use to solve one problem. If during the convergence check this limit is exceeded, Ipopt will terminate with a corresponding error message. The valid range for this real option is 0 < max_cpu_time < +inf and its default value is 1E6. ';
            app.uipanel4 = uipanel(app.GridLayout8);
            app.uipanel4.Title = 'Parallelization';
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout10 = uigridlayout(app.uipanel4);
            app.useParallelCombo = uidropdown(app.GridLayout10);
            app.useParallelCombo.Items = {'Pop-up Menu'};
            app.useParallelCombo.Tag = 'useParallelCombo';
            app.useParallelCombo.Tooltip = 'When selected, IPOPT estimates gradients in parallel.  Parallel calculation of gradients can speed up the optimization.';
            app.useParallelCombo.Value = 'Pop-up Menu';
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.text16 = uilabel(app.GridLayout11);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Num. Parallel Workers';
            app.numParaWorkersText = uieditfield(app.GridLayout11, 'text');
            app.numParaWorkersText.ValueChangedFcn = createCallbackFcn(app, @numParaWorkersText_Callback, true);
            app.numParaWorkersText.Tag = 'numParaWorkersText';
            app.numParaWorkersText.Tooltip = 'The number of parallel workers to use for computing the gradients.';
            app.numParaWorkersText.Value = '0.0';
            % Show the figure after all components are created
            app.lvd_editIpoptOptionsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_editIpoptOptionsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_editIpoptOptionsGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_editIpoptOptionsGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_editIpoptOptionsGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_editIpoptOptionsGUI)
        end
    end
end