classdef lvd_editNomadOptionsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_editNomadOptionsGUI       matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        saveAndCloseButton            matlab.ui.control.Button
        uipanel1                      matlab.ui.container.Panel
        GridLayout8                   matlab.ui.container.GridLayout
        Hyperlink                     matlab.ui.control.Hyperlink
        text15                        matlab.ui.control.Label
        docLinkLabel                  matlab.ui.control.Label
        GridLayout2                   matlab.ui.container.GridLayout
        GridLayout9                   matlab.ui.container.GridLayout
        LatinHypercubeSearchPanel     matlab.ui.container.Panel
        GridLayout17                  matlab.ui.container.GridLayout
        lhSearchEvals                 matlab.ui.control.EditField
        lhSearchInitEvals             matlab.ui.control.EditField
        text6_3                       matlab.ui.control.Label
        text6_2                       matlab.ui.control.Label
        uipanel5                      matlab.ui.container.Panel
        GridLayout14                  matlab.ui.container.GridLayout
        GridLayout15                  matlab.ui.container.GridLayout
        numParaWorkersText            matlab.ui.control.EditField
        text16                        matlab.ui.control.Label
        useParallelCombo              matlab.ui.control.DropDown
        uipanel6                      matlab.ui.container.Panel
        GridLayout13                  matlab.ui.container.GridLayout
        vnsTriggerText                matlab.ui.control.EditField
        vnsTriggerLabel               matlab.ui.control.Label
        uipanel4                      matlab.ui.container.Panel
        GridLayout10                  matlab.ui.container.GridLayout
        GridLayout12                  matlab.ui.container.GridLayout
        hMinText                      matlab.ui.control.EditField
        text10                        matlab.ui.control.Label
        GridLayout11                  matlab.ui.container.GridLayout
        hMax0Text                     matlab.ui.control.EditField
        hMax0Label                    matlab.ui.control.Label
        hNormCombo                    matlab.ui.control.DropDown
        constrTypeCombo               matlab.ui.control.DropDown
        GridLayout3                   matlab.ui.container.GridLayout
        DiscoMADSPanel                matlab.ui.container.Panel
        GridLayout18                  matlab.ui.container.GridLayout
        EnableDiscoMADSAlgorithmCheckBox  matlab.ui.control.CheckBox
        SurrogateSearchOptionsPanel   matlab.ui.container.Panel
        GridLayout16                  matlab.ui.container.GridLayout
        SurrogateSearchOptionsButton  matlab.ui.control.Button
        UseSurrogateSearchCheckBox    matlab.ui.control.CheckBox
        uipanel3                      matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        GridLayout7                   matlab.ui.container.GridLayout
        maxIterationsText             matlab.ui.control.EditField
        maxCacheMemoryText            matlab.ui.control.EditField
        maxTimeText                   matlab.ui.control.EditField
        maxBbEvalsText                matlab.ui.control.EditField
        text7                         matlab.ui.control.Label
        text4                         matlab.ui.control.Label
        text5                         matlab.ui.control.Label
        text6                         matlab.ui.control.Label
        stopIfFeasibleCombo           matlab.ui.control.DropDown
        uipanel2                      matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        GridLayout5                   matlab.ui.container.GridLayout
        initialMeshSizeText           matlab.ui.control.EditField
        text3                         matlab.ui.control.Label
        directionTypeCombo            matlab.ui.control.DropDown
        text2                         matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function handles = populateGUI(app, handles, nomadOpt)
            options = nomadOpt.getOptions();

            dirTypeStrs = NomadDirectionTypeEnum.getListBoxStr();
            handles.directionTypeCombo.String = dirTypeStrs;
            handles.directionTypeCombo.Value = NomadDirectionTypeEnum.getIndForName(options.direction_type.name);

            stopIfFeasibleStrs = NomadStopIfFeasibleEnum.getListBoxStr();
            handles.stopIfFeasibleCombo.String = stopIfFeasibleStrs;
            handles.stopIfFeasibleCombo.Value = NomadStopIfFeasibleEnum.getIndForName(options.stop_if_feasible.name);

            constrTypeStrs = NomadConstraintType.getListBoxStr();
            handles.constrTypeCombo.String = constrTypeStrs;
            handles.constrTypeCombo.Value = NomadConstraintType.getIndForName(options.constrType.name);

            hNormStrs = NomadHNormTypeEnum.getListBoxStr();
            handles.hNormCombo.String = hNormStrs;
            handles.hNormCombo.Value = NomadHNormTypeEnum.getIndForName(options.h_norm.name);

            useParallelStrs = PatternSearchUseParallelEnum.getListBoxStr();
            handles.useParallelCombo.String = useParallelStrs;
            handles.useParallelCombo.Value = PatternSearchUseParallelEnum.getIndForName(options.useParallel.name);

            setOptsDoubleValueStrInGUI(handles, options, 'initial_mesh_size', 'initialMeshSizeText');

            setOptsDoubleValueStrInGUI(handles, options, 'max_bb_eval', 'maxBbEvalsText');
            setOptsDoubleValueStrInGUI(handles, options, 'max_time', 'maxTimeText');
            setOptsDoubleValueStrInGUI(handles, options, 'max_cache_memory', 'maxCacheMemoryText');
            setOptsDoubleValueStrInGUI(handles, options, 'max_iterations', 'maxIterationsText');

            setOptsDoubleValueStrInGUI(handles, options, 'h_max_0', 'hMax0Text');
            setOptsDoubleValueStrInGUI(handles, options, 'h_min', 'hMinText');

            setOptsDoubleValueStrInGUI(handles, options, 'vns_trigger', 'vnsTriggerText');

            setOptsDoubleValueStrInGUI(handles, options, 'vns_trigger', 'vnsTriggerText');

            setOptsDoubleValueStrInGUI(handles, options, 'numWorkers', 'numParaWorkersText');

            app.UseSurrogateSearchCheckBox.Value = options.useSurrogateModelSearch;
            if(app.UseSurrogateSearchCheckBox.Value)
                app.SurrogateSearchOptionsButton.Enable = "on";
            else
                app.SurrogateSearchOptionsButton.Enable = "off";
            end

            setOptsDoubleValueStrInGUI(handles, options, 'lhSearchInitEvals', 'lhSearchInitEvals');
            setOptsDoubleValueStrInGUI(handles, options, 'lhSearchEvals', 'lhSearchEvals');

            if(options.useDiscoMads)
                app.EnableDiscoMADSAlgorithmCheckBox.Value = 1;
            else
                app.EnableDiscoMADSAlgorithmCheckBox.Value = 0;
            end
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            errMsg = validateDoubleValue(handles, errMsg, 'initialMeshSizeText', 'Cache Tolerance', 1E-12, Inf, false);

            errMsg = validateDoubleValue(handles, errMsg, 'maxBbEvalsText', 'Max Function Evals', 1, Inf, true);
            errMsg = validateDoubleValue(handles, errMsg, 'maxTimeText', 'Max Time (s)', 1, Inf, false);
            errMsg = validateDoubleValue(handles, errMsg, 'maxCacheMemoryText', 'Max Cache Memory (MB)', 1, Inf, true);
            errMsg = validateDoubleValue(handles, errMsg, 'maxIterationsText', 'Max Iterations', 1, Inf, true);

            errMsg = validateDoubleValue(handles, errMsg, 'hMax0Text', 'Initial Hmax Factor', 1, Inf, false);
            errMsg = validateDoubleValue(handles, errMsg, 'hMinText', 'Constraint Tolerance', 1E-12, Inf, false);

            errMsg = validateDoubleValue(handles, errMsg, 'vnsTriggerText', 'VNS Trigger', 0, 1, false);

            errMsg = validateDoubleValue(handles, errMsg, 'vnsTriggerText', 'VNS Trigger', 0, 1, false);

            errMsg = validateDoubleValue(handles, errMsg, 'numParaWorkersText', 'Number of Parallel Workers', 1, 2*feature('numCores'), true);

            errMsg = validateDoubleValue(handles, errMsg, 'lhSearchInitEvals', 'Latin Hypercube Initial Evaluations', 0, inf, true);
            errMsg = validateDoubleValue(handles, errMsg, 'lhSearchEvals', 'Latin Hypercube Search Evaluations', 0, inf, true);
        end


        function saveAndClose(app, handles)
            nomadOpt = getappdata(handles.lvd_editNomadOptionsGUI,'nomadOpt');
            options = nomadOpt.getOptions();

            dirTypeStrs = NomadDirectionTypeEnum.getListBoxStr();
            options.direction_type = NomadDirectionTypeEnum.getEnumForListboxStr(dirTypeStrs{handles.directionTypeCombo.Value});

            stopIfFeasibleStrs = NomadStopIfFeasibleEnum.getListBoxStr();
            options.stop_if_feasible = NomadStopIfFeasibleEnum.getEnumForListboxStr(stopIfFeasibleStrs{handles.stopIfFeasibleCombo.Value});

            constrTypeStrs = NomadConstraintType.getListBoxStr();
            options.constrType = NomadConstraintType.getEnumForListboxStr(constrTypeStrs{handles.constrTypeCombo.Value});

            hNormStrs = NomadHNormTypeEnum.getListBoxStr();
            options.h_norm = NomadHNormTypeEnum.getEnumForListboxStr(hNormStrs{handles.hNormCombo.Value});

            useParallelStrs = PatternSearchUseParallelEnum.getListBoxStr();
            options.useParallel = PatternSearchUseParallelEnum.getEnumForListboxStr(useParallelStrs{handles.useParallelCombo.Value});

            setOptsDoubleValueInObject(handles, options, 'initial_mesh_size', 'initialMeshSizeText');

            setOptsDoubleValueInObject(handles, options, 'max_bb_eval', 'maxBbEvalsText');
            setOptsDoubleValueInObject(handles, options, 'max_time', 'maxTimeText');
            setOptsDoubleValueInObject(handles, options, 'max_cache_memory', 'maxCacheMemoryText');
            setOptsDoubleValueInObject(handles, options, 'max_iterations', 'maxIterationsText');

            setOptsDoubleValueInObject(handles, options, 'h_max_0', 'hMax0Text');
            setOptsDoubleValueInObject(handles, options, 'h_min', 'hMinText');

            setOptsDoubleValueInObject(handles, options, 'vns_trigger', 'vnsTriggerText');

            setOptsDoubleValueInObject(handles, options, 'numWorkers', 'numParaWorkersText');

            options.useSurrogateModelSearch = app.UseSurrogateSearchCheckBox.Value;

            setOptsDoubleValueInObject(handles, options, 'lhSearchInitEvals', 'lhSearchInitEvals');
            setOptsDoubleValueInObject(handles, options, 'lhSearchEvals', 'lhSearchEvals');

            if(app.EnableDiscoMADSAlgorithmCheckBox.Value)
                options.useDiscoMads = true;
                options.useDiscoMadsRevealHiddenConstrs = true;
            else
                options.useDiscoMads = false;
                options.useDiscoMadsRevealHiddenConstrs = false;
            end

            app.output.output = {true};
            close(handles.lvd_editNomadOptionsGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_editNomadOptionsGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_editNomadOptionsGUI (see VARARGIN)

            % Choose default command line output for lvd_editNomadOptionsGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            nomadOpt = varargin{1};
            setappdata(hObject,'nomadOpt',nomadOpt);

            app.output = varargin{2};

            handles = populateGUI(app, handles, nomadOpt);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_editNomadOptionsGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_editNomadOptionsGUI);
        end

        % Value changed function: numParaWorkersText
        function numParaWorkersText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to numParaWorkersText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of numParaWorkersText as text
            %        str2double(get(hObject,'String')) returns contents of numParaWorkersText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                app.saveAndClose(handles);
            else
                uialert(app.lvd_editNomadOptionsGUI, errMsg, 'Invalid NOMAD Options Inputs', "Icon","error");
            end
        end

        % Button pushed function: SurrogateSearchOptionsButton
        function SurrogateSearchOptionsButtonPushed(app, event)
            nomadOpt = getappdata(app.lvd_editNomadOptionsGUI,'nomadOpt');
            options = nomadOpt.getOptions();
            output2 = AppDesignerGUIOutput({false});

            lvd_editNomadSurrogateOptionsGUI_App(options.surrogateOptions, output2);
        end

        % Value changed function: UseSurrogateSearchCheckBox
        function UseSurrogateSearchCheckBoxValueChanged(app, event)
            if(app.UseSurrogateSearchCheckBox.Value)
                app.SurrogateSearchOptionsButton.Enable = "on";
            else
                app.SurrogateSearchOptionsButton.Enable = "off";
            end
        end

        % Value changed function: initialMeshSizeText
        function initialMeshSizeTextValueChanged(app, event)
            app.initialMeshSizeText.Value = attemptStrEval(app.initialMeshSizeText.Value);
        end

        % Value changed function: maxBbEvalsText
        function maxBbEvalsTextValueChanged(app, event)
            app.maxBbEvalsText.Value = attemptStrEval(app.maxBbEvalsText.Value);
        end

        % Value changed function: maxTimeText
        function maxTimeTextValueChanged(app, event)
            app.maxTimeText.Value = attemptStrEval(app.maxTimeText.Value);
        end

        % Value changed function: maxCacheMemoryText
        function maxCacheMemoryTextValueChanged(app, event)
            app.maxCacheMemoryText.Value = attemptStrEval(app.maxCacheMemoryText.Value);
        end

        % Value changed function: maxIterationsText
        function maxIterationsTextValueChanged(app, event)
            app.maxIterationsText.Value = attemptStrEval(app.maxIterationsText.Value);
        end

        % Value changed function: hMax0Text
        function hMax0TextValueChanged(app, event)
            app.hMax0Text.Value = attemptStrEval(app.hMax0Text.Value);
        end

        % Value changed function: hMinText
        function hMinTextValueChanged(app, event)
            app.hMinText.Value = attemptStrEval(app.hMinText.Value);
        end

        % Value changed function: vnsTriggerText
        function vnsTriggerTextValueChanged(app, event)
            app.vnsTriggerText.Value = attemptStrEval(app.vnsTriggerText.Value);
        end

        % Value changed function: lhSearchInitEvals
        function lhSearchInitEvalsValueChanged(app, event)
            app.lhSearchInitEvals.Value = attemptStrEval(app.lhSearchInitEvals.Value);
        end

        % Value changed function: lhSearchEvals
        function lhSearchEvalsValueChanged(app, event)
            app.lhSearchEvals.Value = attemptStrEval(app.lhSearchEvals.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_editNomadOptionsGUI = uifigure('Visible', 'off');
            app.lvd_editNomadOptionsGUI.Name = 'Edit NOMAD Options';
            app.lvd_editNomadOptionsGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_editNomadOptionsGUI.Tag = 'lvd_editNomadOptionsGUI';
            app.GridLayout = uigridlayout(app.lvd_editNomadOptionsGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit NOMAD Options';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout8 = uigridlayout(app.uipanel1);
            app.GridLayout2 = uigridlayout(app.GridLayout8);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Basic Parameters';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4 = uigridlayout(app.uipanel2);
            app.directionTypeCombo = uidropdown(app.GridLayout4);
            app.directionTypeCombo.Items = {'Pop-up Menu'};
            app.directionTypeCombo.Tag = 'directionTypeCombo';
            app.directionTypeCombo.Tooltip = 'Type of Directions for the poll';
            app.directionTypeCombo.Value = 'Pop-up Menu';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.text3 = uilabel(app.GridLayout5);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Initial Mesh Size';
            app.initialMeshSizeText = uieditfield(app.GridLayout5, 'text');
            app.initialMeshSizeText.ValueChangedFcn = createCallbackFcn(app, @initialMeshSizeTextValueChanged, true);
            app.initialMeshSizeText.Tag = 'initialMeshSizeText';
            app.initialMeshSizeText.Tooltip = 'Initial Size of the mesh.';
            app.initialMeshSizeText.Value = '0.0';
            app.uipanel3 = uipanel(app.GridLayout3);
            app.uipanel3.Title = 'Termination Conditions';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout6 = uigridlayout(app.uipanel3);
            app.stopIfFeasibleCombo = uidropdown(app.GridLayout6);
            app.stopIfFeasibleCombo.Items = {'Pop-up Menu'};
            app.stopIfFeasibleCombo.Tag = 'stopIfFeasibleCombo';
            app.stopIfFeasibleCombo.Tooltip = 'NOMAD terminates if it generates a feasible solution';
            app.stopIfFeasibleCombo.Value = 'Pop-up Menu';
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.text6 = uilabel(app.GridLayout7);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Max Function Evals';
            app.text5 = uilabel(app.GridLayout7);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Max Time';
            app.text4 = uilabel(app.GridLayout7);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Max Cache Memory';
            app.text7 = uilabel(app.GridLayout7);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Max Iterations';
            app.maxBbEvalsText = uieditfield(app.GridLayout7, 'text');
            app.maxBbEvalsText.ValueChangedFcn = createCallbackFcn(app, @maxBbEvalsTextValueChanged, true);
            app.maxBbEvalsText.Tag = 'maxBbEvalsText';
            app.maxBbEvalsText.Tooltip = 'The maximum number of blackbox evaluations for one NOMAD run.';
            app.maxBbEvalsText.Value = '0.0';
            app.maxTimeText = uieditfield(app.GridLayout7, 'text');
            app.maxTimeText.ValueChangedFcn = createCallbackFcn(app, @maxTimeTextValueChanged, true);
            app.maxTimeText.Tag = 'maxTimeText';
            app.maxTimeText.Tooltip = 'Maximum wall-clock time (in seconds)';
            app.maxTimeText.Value = '0.0';
            app.maxCacheMemoryText = uieditfield(app.GridLayout7, 'text');
            app.maxCacheMemoryText.ValueChangedFcn = createCallbackFcn(app, @maxCacheMemoryTextValueChanged, true);
            app.maxCacheMemoryText.Tag = 'maxCacheMemoryText';
            app.maxCacheMemoryText.Editable = 'off';
            app.maxCacheMemoryText.Tooltip = 'NOMAD terminates if the cache reaches this memory limit expressed in MB';
            app.maxCacheMemoryText.Value = '0.0';
            app.maxIterationsText = uieditfield(app.GridLayout7, 'text');
            app.maxIterationsText.ValueChangedFcn = createCallbackFcn(app, @maxIterationsTextValueChanged, true);
            app.maxIterationsText.Tag = 'maxIterationsText';
            app.maxIterationsText.Tooltip = 'Max number of MADS iterations';
            app.maxIterationsText.Value = '0.0';
            app.SurrogateSearchOptionsPanel = uipanel(app.GridLayout3);
            app.SurrogateSearchOptionsPanel.Title = 'Surrogate Search Options';
            app.GridLayout16 = uigridlayout(app.SurrogateSearchOptionsPanel);
            app.UseSurrogateSearchCheckBox = uicheckbox(app.GridLayout16);
            app.UseSurrogateSearchCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseSurrogateSearchCheckBoxValueChanged, true);
            app.UseSurrogateSearchCheckBox.Tooltip = {'During a Search step that uses surrogate search, models of the objective and the constraints are constructed and a surrogate subproblem involving these models is optimized. The resulting solutions are the next candidates for evaluation by the true problem.'};
            app.UseSurrogateSearchCheckBox.Text = 'Use Surrogate Search';
            app.SurrogateSearchOptionsButton = uibutton(app.GridLayout16, 'push');
            app.SurrogateSearchOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @SurrogateSearchOptionsButtonPushed, true);
            app.SurrogateSearchOptionsButton.Icon = 'linear-regression.png';
            app.SurrogateSearchOptionsButton.Tooltip = {'Set options for the surrogate search.'};
            app.SurrogateSearchOptionsButton.Text = 'Surrogate Search Options ';
            app.DiscoMADSPanel = uipanel(app.GridLayout3);
            app.DiscoMADSPanel.Title = 'DiscoMADS';
            app.GridLayout18 = uigridlayout(app.DiscoMADSPanel);
            app.GridLayout18.Tooltip = {'The DiscoMADS algorithm reveals and escapes some regions of the space of variables while solving an optimization problem.  It is NOT compatible with parallel processing.'};
            app.EnableDiscoMADSAlgorithmCheckBox = uicheckbox(app.GridLayout18);
            app.EnableDiscoMADSAlgorithmCheckBox.Tooltip = {'The DiscoMADS algorithm reveals and escapes some regions of the space of variables while solving an optimization problem.  It is NOT compatible with parallel processing.'};
            app.EnableDiscoMADSAlgorithmCheckBox.Text = 'Enable DiscoMADS Algorithm';
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.uipanel4 = uipanel(app.GridLayout9);
            app.uipanel4.Title = 'Constraints';
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout10 = uigridlayout(app.uipanel4);
            app.constrTypeCombo = uidropdown(app.GridLayout10);
            app.constrTypeCombo.Items = {'Pop-up Menu'};
            app.constrTypeCombo.Tag = 'constrTypeCombo';
            app.constrTypeCombo.Tooltip = 'The type of constraint handling NOMAD applies.';
            app.constrTypeCombo.Value = 'Pop-up Menu';
            app.hNormCombo = uidropdown(app.GridLayout10);
            app.hNormCombo.Items = {'Pop-up Menu'};
            app.hNormCombo.Tag = 'hNormCombo';
            app.hNormCombo.Tooltip = 'Type of norm used to compute the norm of the constraint vector';
            app.hNormCombo.Value = 'Pop-up Menu';
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.hMax0Label = uilabel(app.GridLayout11);
            app.hMax0Label.Tag = 'hMax0Label';
            app.hMax0Label.Text = 'Initial Hmax Factor';
            app.hMax0Text = uieditfield(app.GridLayout11, 'text');
            app.hMax0Text.ValueChangedFcn = createCallbackFcn(app, @hMax0TextValueChanged, true);
            app.hMax0Text.Tag = 'hMax0Text';
            app.hMax0Text.Tooltip = 'Initial value of h_max (will be eventually decreased throughout the algorithm)';
            app.hMax0Text.Value = '0.0';
            app.GridLayout12 = uigridlayout(app.GridLayout10);
            app.text10 = uilabel(app.GridLayout12);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Constraint Tolerance';
            app.hMinText = uieditfield(app.GridLayout12, 'text');
            app.hMinText.ValueChangedFcn = createCallbackFcn(app, @hMinTextValueChanged, true);
            app.hMinText.Tag = 'hMinText';
            app.hMinText.Tooltip = 'Solution is feasible if the norm of the constraint vector is less than this value.';
            app.hMinText.Value = '0.0';
            app.uipanel6 = uipanel(app.GridLayout9);
            app.uipanel6.Title = 'Variable Neighbor Search';
            app.uipanel6.Tag = 'uipanel6';
            app.GridLayout13 = uigridlayout(app.uipanel6);
            app.vnsTriggerLabel = uilabel(app.GridLayout13);
            app.vnsTriggerLabel.Tag = 'vnsTriggerLabel';
            app.vnsTriggerLabel.Text = 'VNS Trigger';
            app.vnsTriggerText = uieditfield(app.GridLayout13, 'text');
            app.vnsTriggerText.ValueChangedFcn = createCallbackFcn(app, @vnsTriggerTextValueChanged, true);
            app.vnsTriggerText.Tag = 'vnsTriggerText';
            app.vnsTriggerText.Tooltip = 'The Variable Neighborhood Search (VNS) is a strategy to escape local minima.  VNS should only be used for problems with several such local optima. It will cost some additional evaluations, since each search performs another MADS run from a perturbed starting point.  This is the maximum desired ratio of VNS blackbox evaluations over the total number of blackbox evaluations.  Disable with 0.0 or active with 0.0 < x <= 1.0';
            app.vnsTriggerText.Value = '0.0';
            app.uipanel5 = uipanel(app.GridLayout9);
            app.uipanel5.Title = 'Parallelization';
            app.uipanel5.Tag = 'uipanel5';
            app.GridLayout14 = uigridlayout(app.uipanel5);
            app.useParallelCombo = uidropdown(app.GridLayout14);
            app.useParallelCombo.Items = {'Pop-up Menu'};
            app.useParallelCombo.Tag = 'useParallelCombo';
            app.useParallelCombo.Tooltip = 'If in use, computes the objective and nonlinear constraint functions in parallel.';
            app.useParallelCombo.Value = 'Pop-up Menu';
            app.GridLayout15 = uigridlayout(app.GridLayout14);
            app.text16 = uilabel(app.GridLayout15);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Num. Parallel Workers';
            app.numParaWorkersText = uieditfield(app.GridLayout15, 'text');
            app.numParaWorkersText.ValueChangedFcn = createCallbackFcn(app, @numParaWorkersText_Callback, true);
            app.numParaWorkersText.Tag = 'numParaWorkersText';
            app.numParaWorkersText.Tooltip = 'The number of parallel workers to use for computing values.';
            app.numParaWorkersText.Value = '0.0';
            app.LatinHypercubeSearchPanel = uipanel(app.GridLayout9);
            app.LatinHypercubeSearchPanel.Title = 'Latin Hypercube Search';
            app.GridLayout17 = uigridlayout(app.LatinHypercubeSearchPanel);
            app.text6_2 = uilabel(app.GridLayout17);
            app.text6_2.Tag = 'text6';
            app.text6_2.Text = 'Initial Evaluations';
            app.text6_3 = uilabel(app.GridLayout17);
            app.text6_3.Tag = 'text6';
            app.text6_3.Text = 'Search Evaluations';
            app.lhSearchInitEvals = uieditfield(app.GridLayout17, 'text');
            app.lhSearchInitEvals.ValueChangedFcn = createCallbackFcn(app, @lhSearchInitEvalsValueChanged, true);
            app.lhSearchInitEvals.Tag = 'lhSearchInitEvals';
            app.lhSearchInitEvals.Tooltip = {'The number of initial LH search points. These points are evaluated initially, before NOMAD runs.'};
            app.lhSearchInitEvals.Value = '0.0';
            app.lhSearchEvals = uieditfield(app.GridLayout17, 'text');
            app.lhSearchEvals.ValueChangedFcn = createCallbackFcn(app, @lhSearchEvalsValueChanged, true);
            app.lhSearchEvals.Tag = 'lhSearchEvals';
            app.lhSearchEvals.Tooltip = {'The number of LH search points at each iteration.'};
            app.lhSearchEvals.Value = '0.0';
            app.docLinkLabel = uilabel(app.GridLayout8);
            app.docLinkLabel.Tag = 'docLinkLabel';
            app.docLinkLabel.Text = 'For more information, open the documentation PDF for NOMAD:';
            app.text15 = uilabel(app.GridLayout8);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Leave a text field blank to use that setting''s default value.';
            app.Hyperlink = uihyperlink(app.GridLayout8);
            app.Hyperlink.URL = 'NOMAD_Users_Guide.pdf';
            app.Hyperlink.Text = 'NOMAD_Users_Guide.pdf';
            app.saveAndCloseButton = uibutton(app.GridLayout, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            % Show the figure after all components are created
            app.lvd_editNomadOptionsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_editNomadOptionsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_editNomadOptionsGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_editNomadOptionsGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_editNomadOptionsGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_editNomadOptionsGUI)
        end
    end
end