classdef lvd_editNomadSurrogateOptionsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditNOMADSurrogateOptionsUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        ParametersPanel                matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        BudgetPanel                    matlab.ui.container.Panel
        GridLayout14                   matlab.ui.container.GridLayout
        MetricTypeDropDown             matlab.ui.control.DropDown
        MetricTypeDropDownLabel        matlab.ui.control.Label
        BudgetSpinner                  matlab.ui.control.Spinner
        BudgetSpinnerLabel             matlab.ui.control.Label
        ENSEMBLESTATParametersPanel    matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        GridLayout6_13                 matlab.ui.container.GridLayout
        GridLayout6_12                 matlab.ui.container.GridLayout
        LambdaPiEditField              matlab.ui.control.EditField
        LambdaPiEditFieldLabel         matlab.ui.control.Label
        GridLayout6_11                 matlab.ui.container.GridLayout
        LambdaPEditField               matlab.ui.control.EditField
        LambdaPEditFieldLabel          matlab.ui.control.Label
        GridLayout6_10                 matlab.ui.container.GridLayout
        SigmaMultiplierEditField       matlab.ui.control.EditField
        SigmaMultiplierEditFieldLabel  matlab.ui.control.Label
        GridLayout6_9                  matlab.ui.container.GridLayout
        SizeParameterEditField         matlab.ui.control.EditField
        SizeParameterEditFieldLabel    matlab.ui.control.Label
        GridLayout6_8                  matlab.ui.container.GridLayout
        UncertaintyTypeDropDown        matlab.ui.control.DropDown
        UncertaintyTypeDropDownLabel   matlab.ui.control.Label
        GridLayout4                    matlab.ui.container.GridLayout
        ENSEMBLEParametersPanel        matlab.ui.container.Panel
        GridLayout5_6                  matlab.ui.container.GridLayout
        WeightDescLabel                matlab.ui.control.Label
        GridLayout6_6                  matlab.ui.container.GridLayout
        WeightTypeDropDown             matlab.ui.control.DropDown
        WeightTypeDropDownLabel        matlab.ui.control.Label
        DistanceFunctionPanel          matlab.ui.container.Panel
        GridLayout5_5                  matlab.ui.container.GridLayout
        OptimizeDistanceTypeCheckBox   matlab.ui.control.CheckBox
        GridLayout6_5                  matlab.ui.container.GridLayout
        DistanceFunctionDropDown       matlab.ui.control.DropDown
        DistanceFunctionDropDownLabel  matlab.ui.control.Label
        KernelShapePanel               matlab.ui.container.Panel
        GridLayout5_4                  matlab.ui.container.GridLayout
        OptimizeKernelShapeValueCheckBox  matlab.ui.control.CheckBox
        GridLayout6_4                  matlab.ui.container.GridLayout
        KernelShapeEditField           matlab.ui.control.EditField
        KernelShapeEditFieldLabel      matlab.ui.control.Label
        KernelTypePanel                matlab.ui.container.Panel
        GridLayout5_3                  matlab.ui.container.GridLayout
        OptimizeKernelTypeCheckBox     matlab.ui.control.CheckBox
        GridLayout6_3                  matlab.ui.container.GridLayout
        KernelTypeDropDown             matlab.ui.control.DropDown
        KernelTypeDropDownLabel        matlab.ui.control.Label
        RidgePanel                     matlab.ui.container.Panel
        GridLayout5_2                  matlab.ui.container.GridLayout
        OptimizeRidgeValueCheckBox     matlab.ui.control.CheckBox
        GridLayout6_2                  matlab.ui.container.GridLayout
        RidgeEditField                 matlab.ui.control.EditField
        RidgeEditFieldLabel            matlab.ui.control.Label
        DegreePanel                    matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        OptimizeDegreeValueCheckBox    matlab.ui.control.CheckBox
        GridLayout6                    matlab.ui.container.GridLayout
        DegreeSpinner                  matlab.ui.control.Spinner
        DegreeSpinnerLabel             matlab.ui.control.Label
        SurrogateModelTypePanel        matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        Hyperlink                      matlab.ui.control.Hyperlink
        NotallmodelsuseallofthebelowparametersLabel  matlab.ui.control.Label
        ModelTypeDropdown              matlab.ui.control.DropDown
        saveAndCloseButton             matlab.ui.control.Button
        text2                          matlab.ui.control.Label
    end

    
    properties (Access = private)
        nomadSurrOpts NomadSurrogateOptions
        output AppDesignerGUIOutput
    end
    
    methods (Access = private)
        function populateGUI(app)
            [listBoxStr, m] = NomadSurrogateModelTypeEnum.getListBoxStr();
            app.ModelTypeDropdown.Items = listBoxStr;
            app.ModelTypeDropdown.ItemsData = m;
            app.ModelTypeDropdown.Value = app.nomadSurrOpts.type;
            app.ModelTypeDropdown.Tooltip = app.nomadSurrOpts.type.desc;

            %degree
            app.DegreeSpinner.Value = app.nomadSurrOpts.degree;
            app.OptimizeDegreeValueCheckBox.Value = app.nomadSurrOpts.degree_optim;

            %ridge
            app.RidgeEditField.Value = fullAccNum2Str(app.nomadSurrOpts.ridge);
            app.OptimizeRidgeValueCheckBox.Value = app.nomadSurrOpts.ridge_optim;

            %kernel type
            [listBoxStr, m] = NomadSurrogateKernelTypeEnum.getListBoxStr();
            app.KernelTypeDropDown.Items = listBoxStr;
            app.KernelTypeDropDown.ItemsData = m;
            app.KernelTypeDropDown.Value = app.nomadSurrOpts.kernel_type;

            app.OptimizeKernelTypeCheckBox.Value = app.nomadSurrOpts.kernel_type_optim;

            %kernel shape
            app.KernelShapeEditField.Value = fullAccNum2Str(app.nomadSurrOpts.kernel_shape);
            app.OptimizeKernelShapeValueCheckBox.Value = app.nomadSurrOpts.kernel_shape_optim;

            %distance function
            [listBoxStr, m] = NomadSurrogateDistanceTypeEnum.getListBoxStr();
            app.DistanceFunctionDropDown.Items = listBoxStr;
            app.DistanceFunctionDropDown.ItemsData = m;
            app.DistanceFunctionDropDown.Value = app.nomadSurrOpts.distance_type;

            app.OptimizeDistanceTypeCheckBox.Value = app.nomadSurrOpts.distance_optim;

            %ensemble parameters
            [listBoxStr, m] = NomadSurrogateWeightTypeEnum.getListBoxStr();
            app.WeightTypeDropDown.Items = listBoxStr;
            app.WeightTypeDropDown.ItemsData = m;
            app.WeightTypeDropDown.Value = app.nomadSurrOpts.weight;

            [listBoxStr, m] = NomadSurrogateMetricTypeEnum.getListBoxStr();
            app.MetricTypeDropDown.Items = listBoxStr;
            app.MetricTypeDropDown.ItemsData = m;
            app.MetricTypeDropDown.Value = app.nomadSurrOpts.metric;

            %ensemble stats
            [listBoxStr, m] = NomadSurrogateUncertaintyTypeEnum.getListBoxStr();
            app.UncertaintyTypeDropDown.Items = listBoxStr;
            app.UncertaintyTypeDropDown.ItemsData = m;
            app.UncertaintyTypeDropDown.Value = app.nomadSurrOpts.uncertainty;

            app.SizeParameterEditField.Value = fullAccNum2Str(app.nomadSurrOpts.size_param);
            app.SigmaMultiplierEditField.Value = fullAccNum2Str(app.nomadSurrOpts.sigma_mult);
            app.LambdaPEditField.Value = fullAccNum2Str(app.nomadSurrOpts.lambda_p);
            app.LambdaPiEditField.Value = fullAccNum2Str(app.nomadSurrOpts.lambda_pi);

            %budget
            app.BudgetSpinner.Value = app.nomadSurrOpts.budget;

            %do checkbox stuff
            app.setPanelsEnableBasedOnModelType();
            app.setEnablesFromOptimCheckboxes();
            app.WeightDescLabel.Text = app.WeightTypeDropDown.Value.desc;
        end
        
        function saveAndClose(app)
            app.nomadSurrOpts.type = app.ModelTypeDropdown.Value;

            app.nomadSurrOpts.degree = app.DegreeSpinner.Value;
            app.nomadSurrOpts.degree_optim = app.OptimizeDegreeValueCheckBox.Value;

            app.nomadSurrOpts.ridge = str2double(app.RidgeEditField.Value);
            app.nomadSurrOpts.ridge_optim = app.OptimizeRidgeValueCheckBox.Value;

            app.nomadSurrOpts.kernel_type = app.KernelTypeDropDown.Value;
            app.nomadSurrOpts.kernel_type_optim = app.OptimizeKernelTypeCheckBox.Value;

            app.nomadSurrOpts.kernel_shape = str2double(app.KernelShapeEditField.Value);
            app.nomadSurrOpts.kernel_shape_optim = app.OptimizeKernelShapeValueCheckBox.Value;

            app.nomadSurrOpts.distance_type = app.DistanceFunctionDropDown.Value;
            app.nomadSurrOpts.distance_optim = app.OptimizeDistanceTypeCheckBox.Value;

            app.nomadSurrOpts.weight = app.WeightTypeDropDown.Value;
            app.nomadSurrOpts.metric = app.MetricTypeDropDown.Value;

            app.nomadSurrOpts.uncertainty = app.UncertaintyTypeDropDown.Value;
            app.nomadSurrOpts.size_param = str2double(app.SizeParameterEditField.Value);
            app.nomadSurrOpts.sigma_mult = str2double(app.SigmaMultiplierEditField.Value);
            app.nomadSurrOpts.lambda_p = str2double(app.LambdaPEditField.Value);
            app.nomadSurrOpts.lambda_pi = str2double(app.LambdaPiEditField.Value);

            app.nomadSurrOpts.budget = app.BudgetSpinner.Value;

            app.output.output = {true};
            close(app.EditNOMADSurrogateOptionsUIFigure);
        end
        
        function errMsg = validateInputs(app)
            errMsg = {};

            if(not(app.OptimizeDegreeValueCheckBox.Value))
                if(app.ModelTypeDropdown.Value == NomadSurrogateModelTypeEnum.LOWESS)
                    ub = 2;
                else
                    ub = Inf;
                end
                errMsg = validateNumber(app.DegreeSpinner.Value, 'Degree', 1, ub, true, errMsg, num2str(app.DegreeSpinner.Value));
            end

            if(not(app.OptimizeRidgeValueCheckBox.Value))
                errMsg = validateNumber(str2double(app.RidgeEditField.Value), 'Ridge', 0, Inf, false, errMsg, app.RidgeEditField.Value);
            end

            if(not(app.OptimizeKernelShapeValueCheckBox.Value))
                errMsg = validateNumber(str2double(app.KernelShapeEditField.Value), 'Kernel Shape', 0, 10, false, errMsg, app.KernelShapeEditField.Value);
            end

            errMsg = validateNumber(str2double(app.SizeParameterEditField.Value), 'Size Parameter', 0, 1, false, errMsg, app.SizeParameterEditField.Value);
            errMsg = validateNumber(str2double(app.SigmaMultiplierEditField.Value), 'Sigma Multipler', 0, 100, false, errMsg, app.SigmaMultiplierEditField.Value);
            errMsg = validateNumber(str2double(app.LambdaPEditField.Value), 'Lambda P', 0, 10, false, errMsg, app.LambdaPEditField.Value);
            errMsg = validateNumber(str2double(app.LambdaPiEditField.Value), 'Lambda Pi', 0, 10, false, errMsg, app.LambdaPiEditField.Value);
        end
        
        function setEnablesFromOptimCheckboxes(app)
            if(app.OptimizeDegreeValueCheckBox.Value)
                app.DegreeSpinner.Enable = "off";
            else
                app.DegreeSpinner.Enable = "on";
            end   

            if(app.OptimizeRidgeValueCheckBox.Value)
                app.RidgeEditField.Enable = "off";
            else
                app.RidgeEditField.Enable = "on";
            end   

            if(app.OptimizeKernelTypeCheckBox.Value)
                app.KernelTypeDropDown.Enable = "off";
            else
                app.KernelTypeDropDown.Enable = "on";
            end  

            if(app.OptimizeKernelTypeCheckBox.Value)
                app.KernelTypeDropDown.Enable = "off";
            else
                app.KernelTypeDropDown.Enable = "on";
            end  

            if(app.OptimizeKernelShapeValueCheckBox.Value)
                app.KernelShapeEditField.Enable = "off";
            else
                app.KernelShapeEditField.Enable = "on";
            end 

            if(app.OptimizeDistanceTypeCheckBox.Value)
                app.DistanceFunctionDropDown.Enable = "off";
            else
                app.DistanceFunctionDropDown.Enable = "on";
            end 
        end
        
        function setEnablesForParameterBoxes(app, degreeEnable, ridgeEnable, kernelTypeEnable, kernelShapeEnable, distanceEnable, ensembleEnable, ensembleStatEnable)
            arguments
                app(1,1) lvd_editNomadSurrogateOptionsGUI_App
                degreeEnable(1,1) string
                ridgeEnable(1,1) string
                kernelTypeEnable(1,1) string
                kernelShapeEnable(1,1) string
                distanceEnable(1,1) string
                ensembleEnable(1,1) string
                ensembleStatEnable(1,1) string
            end
            
            app.DegreePanel.Enable = degreeEnable;
            app.RidgePanel.Enable = ridgeEnable;
            app.KernelTypePanel.Enable = kernelTypeEnable;
            app.KernelShapePanel.Enable = kernelShapeEnable;
            app.DistanceFunctionPanel.Enable = distanceEnable;
            app.ENSEMBLEParametersPanel.Enable = ensembleEnable;
            app.ENSEMBLESTATParametersPanel.Enable = ensembleStatEnable;
        end
        
        function setPanelsEnableBasedOnModelType(app)
            enum = app.ModelTypeDropdown.Value;
            switch enum
                case NomadSurrogateModelTypeEnum.PRS
                    app.setEnablesForParameterBoxes("on", "on", "off", "off", "off", "off", "off")

                case NomadSurrogateModelTypeEnum.PRS_EDGE
                    app.setEnablesForParameterBoxes("on", "on", "off", "off", "off", "off", "off")

                case NomadSurrogateModelTypeEnum.PRS_CAT
                    app.setEnablesForParameterBoxes("on", "on", "off", "off", "off", "off", "off")

                case NomadSurrogateModelTypeEnum.RBF
                    app.setEnablesForParameterBoxes("off", "on", "on", "on", "on", "off", "off")

                case NomadSurrogateModelTypeEnum.KS
                    app.setEnablesForParameterBoxes("off", "off", "on", "on", "on", "off", "off")

                case NomadSurrogateModelTypeEnum.KRIGING
                    app.setEnablesForParameterBoxes("off", "on", "off", "off", "on", "off", "off")

                case NomadSurrogateModelTypeEnum.LOWESS
                    app.setEnablesForParameterBoxes("on", "on", "on", "on", "on", "off", "off")

                case NomadSurrogateModelTypeEnum.CN
                    app.setEnablesForParameterBoxes("off", "off", "off", "off", "on", "off", "off")

                case NomadSurrogateModelTypeEnum.ENSEMBLE
                    app.setEnablesForParameterBoxes("off", "off", "off", "off", "on", "on", "off")
    
                case NomadSurrogateModelTypeEnum.ENSEMBLE_STAT
                    app.setEnablesForParameterBoxes("off", "off", "off", "off", "on", "on", "on")

                otherwise 
                    error('Unknown surrogate model type: %s', enum.name);
            end

            app.setEnablesFromOptimCheckboxes();
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, nomadSurrOpts, output)
            app.nomadSurrOpts = nomadSurrOpts;
            app.output = output;
            
            centerUIFigure(app.EditNOMADSurrogateOptionsUIFigure);
            applySelectedThemeToApp(app);

            app.populateGUI();

            uiwait(app.EditNOMADSurrogateOptionsUIFigure);
        end

        % Value changed function: OptimizeDegreeValueCheckBox
        function OptimizeDegreeValueCheckBoxValueChanged(app, event)
            if(app.OptimizeDegreeValueCheckBox.Value)
                app.DegreeSpinner.Enable = "off";
            else
                app.DegreeSpinner.Enable = "on";
            end            
        end

        % Value changed function: OptimizeRidgeValueCheckBox
        function OptimizeRidgeValueCheckBoxValueChanged(app, event)
            if(app.OptimizeRidgeValueCheckBox.Value)
                app.RidgeEditField.Enable = "off";
            else
                app.RidgeEditField.Enable = "on";
            end      
        end

        % Value changed function: OptimizeKernelTypeCheckBox
        function OptimizeKernelTypeCheckBoxValueChanged(app, event)
            if(app.OptimizeKernelTypeCheckBox.Value)
                app.KernelTypeDropDown.Enable = "off";
            else
                app.KernelTypeDropDown.Enable = "on";
            end  
        end

        % Value changed function: OptimizeKernelShapeValueCheckBox
        function OptimizeKernelShapeValueCheckBoxValueChanged(app, event)
            if(app.OptimizeKernelShapeValueCheckBox.Value)
                app.KernelShapeEditField.Enable = "off";
            else
                app.KernelShapeEditField.Enable = "on";
            end 
        end

        % Value changed function: OptimizeDistanceTypeCheckBox
        function OptimizeDistanceTypeCheckBoxValueChanged(app, event)
            if(app.OptimizeDistanceTypeCheckBox.Value)
                app.DistanceFunctionDropDown.Enable = "off";
            else
                app.DistanceFunctionDropDown.Enable = "on";
            end 
        end

        % Value changed function: ModelTypeDropdown
        function ModelTypeDropdownValueChanged(app, event)
            app.ModelTypeDropdown.Tooltip = app.ModelTypeDropdown.Value.desc;
            app.setPanelsEnableBasedOnModelType();
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButtonPushed(app, event)
            errMsg = app.validateInputs();

            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.EditNOMADSurrogateOptionsUIFigure, errMsg, 'Invalid NOMAD Surrogate Options Inputs', "Icon","error");
            end
        end

        % Value changed function: WeightTypeDropDown
        function WeightTypeDropDownValueChanged(app, event)
            app.WeightDescLabel.Text = app.WeightTypeDropDown.Value.desc;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditNOMADSurrogateOptionsUIFigure = uifigure('Visible', 'off');
            app.EditNOMADSurrogateOptionsUIFigure.Name = 'Edit NOMAD Surrogate Options';
            app.EditNOMADSurrogateOptionsUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout = uigridlayout(app.EditNOMADSurrogateOptionsUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit NOMAD Surrogate Options';
            app.saveAndCloseButton = uibutton(app.GridLayout, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButtonPushed, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SurrogateModelTypePanel = uipanel(app.GridLayout2);
            app.SurrogateModelTypePanel.Title = 'Surrogate Model Type';
            app.GridLayout3 = uigridlayout(app.SurrogateModelTypePanel);
            app.ModelTypeDropdown = uidropdown(app.GridLayout3);
            app.ModelTypeDropdown.ValueChangedFcn = createCallbackFcn(app, @ModelTypeDropdownValueChanged, true);
            app.NotallmodelsuseallofthebelowparametersLabel = uilabel(app.GridLayout3);
            app.NotallmodelsuseallofthebelowparametersLabel.Text = 'Not all models use all of the below parameters.  See NOMAD documentation for more details. ';
            app.Hyperlink = uihyperlink(app.GridLayout3);
            app.Hyperlink.URL = 'https://nomad-4-user-guide.readthedocs.io/en/latest/SgteLib.html';
            app.Hyperlink.Text = 'NOMAD Surrogate Parameter Documentation';
            app.ParametersPanel = uipanel(app.GridLayout2);
            app.ParametersPanel.Title = 'Parameters';
            app.GridLayout8 = uigridlayout(app.ParametersPanel);
            app.GridLayout4 = uigridlayout(app.GridLayout8);
            app.DegreePanel = uipanel(app.GridLayout4);
            app.DegreePanel.Title = 'Degree';
            app.GridLayout5 = uigridlayout(app.DegreePanel);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.DegreeSpinnerLabel = uilabel(app.GridLayout6);
            app.DegreeSpinnerLabel.Text = 'Degree';
            app.DegreeSpinner = uispinner(app.GridLayout6);
            app.DegreeSpinner.Limits = [1 Inf];
            app.DegreeSpinner.RoundFractionalValues = 'on';
            app.DegreeSpinner.Tooltip = {'The field name DEGREE defines the degree of a polynomial response surface. The value must be an integer >= 1. '; ' Allowed for models of type: PRS, PRS_EDGE, PRS_CAT and LOWESS.  Default value: 5'};
            app.DegreeSpinner.Value = 1;
            app.OptimizeDegreeValueCheckBox = uicheckbox(app.GridLayout5);
            app.OptimizeDegreeValueCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptimizeDegreeValueCheckBoxValueChanged, true);
            app.OptimizeDegreeValueCheckBox.Tooltip = {'When checked, the degree of the polynomial response surface will be optimized.'};
            app.OptimizeDegreeValueCheckBox.Text = 'Optimize Degree Value';
            app.RidgePanel = uipanel(app.GridLayout4);
            app.RidgePanel.Title = 'Ridge';
            app.GridLayout5_2 = uigridlayout(app.RidgePanel);
            app.GridLayout6_2 = uigridlayout(app.GridLayout5_2);
            app.RidgeEditFieldLabel = uilabel(app.GridLayout6_2);
            app.RidgeEditFieldLabel.Text = 'Ridge';
            app.RidgeEditField = uieditfield(app.GridLayout6_2, 'text');
            app.RidgeEditField.Tooltip = {'The field name RIDGE defines the regularization parameter of the model.'; 'Allowed for models of type: PRS, PRS_EDGE, PRS_CAT, RBF, KRIGING and LOWESS.  Possible values: Real value >= 0.  Recommended values are 0 and 0.001.  Default value: 0.001.'};
            app.OptimizeRidgeValueCheckBox = uicheckbox(app.GridLayout5_2);
            app.OptimizeRidgeValueCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptimizeRidgeValueCheckBoxValueChanged, true);
            app.OptimizeRidgeValueCheckBox.Tooltip = {'When checked, the ridge value will be optimized.'};
            app.OptimizeRidgeValueCheckBox.Text = 'Optimize Ridge Value';
            app.KernelTypePanel = uipanel(app.GridLayout4);
            app.KernelTypePanel.Title = 'Kernel Type';
            app.GridLayout5_3 = uigridlayout(app.KernelTypePanel);
            app.GridLayout6_3 = uigridlayout(app.GridLayout5_3);
            app.KernelTypeDropDownLabel = uilabel(app.GridLayout6_3);
            app.KernelTypeDropDownLabel.Text = 'Kernel Type';
            app.KernelTypeDropDown = uidropdown(app.GridLayout6_3);
            app.KernelTypeDropDown.Tooltip = {'The field name KERNEL_TYPE defines the type of kernel used in the model. '; 'Allowed for models of type: RBF, LOWESS and KS.'};
            app.OptimizeKernelTypeCheckBox = uicheckbox(app.GridLayout5_3);
            app.OptimizeKernelTypeCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptimizeKernelTypeCheckBoxValueChanged, true);
            app.OptimizeKernelTypeCheckBox.Tooltip = {'When checked, the kernel type will be optimized.'};
            app.OptimizeKernelTypeCheckBox.Text = 'Optimize Kernel Type';
            app.KernelShapePanel = uipanel(app.GridLayout4);
            app.KernelShapePanel.Title = 'Kernel Shape';
            app.GridLayout5_4 = uigridlayout(app.KernelShapePanel);
            app.GridLayout6_4 = uigridlayout(app.GridLayout5_4);
            app.KernelShapeEditFieldLabel = uilabel(app.GridLayout6_4);
            app.KernelShapeEditFieldLabel.Text = 'Kernel Shape';
            app.KernelShapeEditField = uieditfield(app.GridLayout6_4, 'text');
            app.KernelShapeEditField.Tooltip = {'The field name KERNEL_SHAPE defines the shape coefficient of the kernel function. The field name KERNEL_COEF is equivalent. Note that this field name has no impact for kernel types I1, I2, I3 and I4 because these kernels do not include a shape parameter.'; 'Allowed for models of type: RBF, KS and LOWESS.'; 'Possible values: Real value >= 0.  Recommended range is [0.1;10]. For KS and LOWESS model, small values lead to smoother models.'};
            app.OptimizeKernelShapeValueCheckBox = uicheckbox(app.GridLayout5_4);
            app.OptimizeKernelShapeValueCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptimizeKernelShapeValueCheckBoxValueChanged, true);
            app.OptimizeKernelShapeValueCheckBox.Tooltip = {'When checked, the kernel shape value will be optimized.'};
            app.OptimizeKernelShapeValueCheckBox.Text = 'Optimize Kernel Shape Value';
            app.DistanceFunctionPanel = uipanel(app.GridLayout4);
            app.DistanceFunctionPanel.Title = 'Distance Function';
            app.GridLayout5_5 = uigridlayout(app.DistanceFunctionPanel);
            app.GridLayout6_5 = uigridlayout(app.GridLayout5_5);
            app.DistanceFunctionDropDownLabel = uilabel(app.GridLayout6_5);
            app.DistanceFunctionDropDownLabel.Text = 'Distance Function';
            app.DistanceFunctionDropDown = uidropdown(app.GridLayout6_5);
            app.DistanceFunctionDropDown.Tooltip = {'The field name DISTANCE_TYPE defines the distance function used in the model.'; 'Allowed for models of type: RBF, KS, KRIGING, LOWESS, CN, ENSEMBLE and ENSEMBLE_STAT.'};
            app.OptimizeDistanceTypeCheckBox = uicheckbox(app.GridLayout5_5);
            app.OptimizeDistanceTypeCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptimizeDistanceTypeCheckBoxValueChanged, true);
            app.OptimizeDistanceTypeCheckBox.Tooltip = {'When checked, the distance function will be optimized.'};
            app.OptimizeDistanceTypeCheckBox.Text = 'Optimize Distance Type';
            app.ENSEMBLEParametersPanel = uipanel(app.GridLayout4);
            app.ENSEMBLEParametersPanel.Title = 'ENSEMBLE Parameters';
            app.GridLayout5_6 = uigridlayout(app.ENSEMBLEParametersPanel);
            app.GridLayout6_6 = uigridlayout(app.GridLayout5_6);
            app.WeightTypeDropDownLabel = uilabel(app.GridLayout6_6);
            app.WeightTypeDropDownLabel.Text = 'Weight Type';
            app.WeightTypeDropDown = uidropdown(app.GridLayout6_6);
            app.WeightTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @WeightTypeDropDownValueChanged, true);
            app.WeightTypeDropDown.Tooltip = {'The field name WEIGHT defines the method used to compute the weights'; 'of the ensemble of models.  Allowed for models of type: ENSEMBLE and ENSEMBLE_STAT.'; ''; 'Where eta_k is the error metric (defined by the field name METRIC) of the kth model in the ensemble, eta_sum is the cumulated error of all models, eta_min is the minimal error, eta_mean is the average error, alpha=0.05, beta=-1, and eta_sum^N is the cumulated error metric of the best models.'};
            app.WeightDescLabel = uilabel(app.GridLayout5_6);
            app.WeightDescLabel.Interpreter = 'latex';
            app.WeightDescLabel.Text = '$w_k \propto 1$ if $\mathcal{E}_k = \mathcal{E}_{min}$';
            app.ENSEMBLESTATParametersPanel = uipanel(app.GridLayout8);
            app.ENSEMBLESTATParametersPanel.Title = 'ENSEMBLE STAT Parameters';
            app.GridLayout9 = uigridlayout(app.ENSEMBLESTATParametersPanel);
            app.GridLayout6_8 = uigridlayout(app.GridLayout9);
            app.UncertaintyTypeDropDownLabel = uilabel(app.GridLayout6_8);
            app.UncertaintyTypeDropDownLabel.Text = 'Uncertainty Type';
            app.UncertaintyTypeDropDown = uidropdown(app.GridLayout6_8);
            app.UncertaintyTypeDropDown.Tooltip = {'The field name UNCERTAINTY defines the type of uncertainty used in ENSEMBLE_STAT models.'};
            app.GridLayout6_9 = uigridlayout(app.GridLayout9);
            app.SizeParameterEditFieldLabel = uilabel(app.GridLayout6_9);
            app.SizeParameterEditFieldLabel.Text = 'Size Parameter';
            app.SizeParameterEditField = uieditfield(app.GridLayout6_9, 'text');
            app.SizeParameterEditField.Tooltip = {'The field name SIZE_PARAM defines the size of the directions of either:'; ''; '* the simplex used to compute the simplex gradients of the models if the field UNCERTAINTY is set to SMOOTH'; ''; '* the positive spanning set used to compare models values if the field UNCERTAINTY is set to NONSMOOTH'; ''; 'Possible values: Real value >= 0. Recommended range is [0.001; 0.1].'};
            app.GridLayout6_10 = uigridlayout(app.GridLayout9);
            app.SigmaMultiplierEditFieldLabel = uilabel(app.GridLayout6_10);
            app.SigmaMultiplierEditFieldLabel.Text = 'Sigma Multiplier';
            app.SigmaMultiplierEditField = uieditfield(app.GridLayout6_10, 'text');
            app.SigmaMultiplierEditField.Tooltip = {'The field name SIGMA_MULT defines the scaling factor of the uncertain to be multiplied by the variance of already sampled function values.'; 'Possible values: Real value >= 0.  Recommended range is [1,100].'};
            app.GridLayout6_11 = uigridlayout(app.GridLayout9);
            app.LambdaPEditFieldLabel = uilabel(app.GridLayout6_11);
            app.LambdaPEditFieldLabel.Text = 'Lambda P';
            app.LambdaPEditField = uieditfield(app.GridLayout6_11, 'text');
            app.LambdaPEditField.Tooltip = {'The field name LAMBDA_P defines the shape parameter of the probability of feasibility (P).  Possible values: Real value >= 0.  Recommended range is [0.1;10].'};
            app.GridLayout6_12 = uigridlayout(app.GridLayout9);
            app.LambdaPiEditFieldLabel = uilabel(app.GridLayout6_12);
            app.LambdaPiEditFieldLabel.Text = 'Lambda Pi';
            app.LambdaPiEditField = uieditfield(app.GridLayout6_12, 'text');
            app.LambdaPiEditField.Tooltip = {'The field name LAMBDA_PI defines the shape parameter of the probability of improvement (PI).  Possible values: Real value >= 0.  Recommended range is [0.01; 3].'};
            app.GridLayout6_13 = uigridlayout(app.GridLayout9);
            app.BudgetPanel = uipanel(app.GridLayout8);
            app.BudgetPanel.Title = 'Budget';
            app.GridLayout14 = uigridlayout(app.BudgetPanel);
            app.BudgetSpinnerLabel = uilabel(app.GridLayout14);
            app.BudgetSpinnerLabel.Text = 'Budget';
            app.BudgetSpinner = uispinner(app.GridLayout14);
            app.BudgetSpinner.Limits = [5 Inf];
            app.BudgetSpinner.Tooltip = {'Budget for model parameter optimization. The number of sets of model parameters that are tested is equal to the optimization budget multiplied by the number of parameters to optimize.'; 'Allowed for ALL types of model.'};
            app.BudgetSpinner.Value = 5;
            app.MetricTypeDropDownLabel = uilabel(app.GridLayout14);
            app.MetricTypeDropDownLabel.Text = 'Metric Type';
            app.MetricTypeDropDown = uidropdown(app.GridLayout14);
            app.MetricTypeDropDown.Tooltip = {'The field name METRIC defines the metric used to select the parameters of the model (including the weights of Ensemble models).'; 'Allowed for ALL types of model.'};
            % Show the figure after all components are created
            app.EditNOMADSurrogateOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_editNomadSurrogateOptionsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditNOMADSurrogateOptionsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditNOMADSurrogateOptionsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditNOMADSurrogateOptionsUIFigure)
        end
    end
end