classdef lvd_editPatternSearchOptionsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_editPatternSearchOptionsGUI  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        uipanel1                matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        GridLayout3             matlab.ui.container.GridLayout
        GridLayout11            matlab.ui.container.GridLayout
        uipanel9                matlab.ui.container.Panel
        GridLayout15            matlab.ui.container.GridLayout
        GridLayout16            matlab.ui.container.GridLayout
        numParaWorkersText      matlab.ui.control.EditField
        text21                  matlab.ui.control.Label
        useParallelCombo        matlab.ui.control.DropDown
        uipanel8                matlab.ui.container.Panel
        GridLayout14            matlab.ui.container.GridLayout
        penaltyFactText         matlab.ui.control.EditField
        initPenaltyText         matlab.ui.control.EditField
        text17                  matlab.ui.control.Label
        text18                  matlab.ui.control.Label
        uipanel7                matlab.ui.container.Panel
        GridLayout13            matlab.ui.container.GridLayout
        useCompleteSearchCombo  matlab.ui.control.DropDown
        scaleMeshCombo          matlab.ui.control.DropDown
        searchFuncCombo         matlab.ui.control.DropDown
        uipanel6                matlab.ui.container.Panel
        GridLayout12            matlab.ui.container.GridLayout
        useCompletePollCombo    matlab.ui.control.DropDown
        pollOrderCombo          matlab.ui.control.DropDown
        pollMethodCombo         matlab.ui.control.DropDown
        GridLayout4             matlab.ui.container.GridLayout
        uipanel5                matlab.ui.container.Panel
        GridLayout9             matlab.ui.container.GridLayout
        GridLayout10            matlab.ui.container.GridLayout
        meshExpFactorText       matlab.ui.control.EditField
        meshContrFactorText     matlab.ui.control.EditField
        initMeshSizeText        matlab.ui.control.EditField
        text12                  matlab.ui.control.Label
        text13                  matlab.ui.control.Label
        text14                  matlab.ui.control.Label
        useMeshRotateCombo      matlab.ui.control.DropDown
        accelMeshCombo          matlab.ui.control.DropDown
        uipanel3                matlab.ui.container.Panel
        GridLayout7             matlab.ui.container.GridLayout
        GridLayout8             matlab.ui.container.GridLayout
        cacheSizeText           matlab.ui.control.EditField
        text8                   matlab.ui.control.Label
        useCacheCombo           matlab.ui.control.DropDown
        uipanel4                matlab.ui.container.Panel
        GridLayout6             matlab.ui.container.GridLayout
        maxFunEvalsText         matlab.ui.control.EditField
        maxIterText             matlab.ui.control.EditField
        text9                   matlab.ui.control.Label
        text10                  matlab.ui.control.Label
        uipanel2                matlab.ui.container.Panel
        GridLayout5             matlab.ui.container.GridLayout
        stepTolText             matlab.ui.control.EditField
        meshTolText             matlab.ui.control.EditField
        funcTolText             matlab.ui.control.EditField
        conTolText              matlab.ui.control.EditField
        cacheTolText            matlab.ui.control.EditField
        text7                   matlab.ui.control.Label
        text6                   matlab.ui.control.Label
        text3                   matlab.ui.control.Label
        text4                   matlab.ui.control.Label
        text5                   matlab.ui.control.Label
        Hyperlink               matlab.ui.control.Hyperlink
        matlabDocLinkLabel      matlab.ui.control.Label
        text20                  matlab.ui.control.Label
        saveAndCloseButton      matlab.ui.control.Button
        text2                   matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function handles = populateGUI(app, handles, patternSearchOpt)
            options = patternSearchOpt.getOptions();

            %                 setDocLbl(app, handles);

            useCacheStrs = PatternSearchUseCacheEnum.getListBoxStr();
            handles.useCacheCombo.String = useCacheStrs;
            handles.useCacheCombo.Value = PatternSearchUseCacheEnum.getIndForName(options.cache.name);

            useCompletePollStrs = PatternSearchUseCompletePollEnum.getListBoxStr();
            handles.useCompletePollCombo.String = useCompletePollStrs;
            handles.useCompletePollCombo.Value = PatternSearchUseCompletePollEnum.getIndForName(options.useCompletePoll.name);

            useCompleteSearchStrs = PatternSearchUseCompleteSearchEnum.getListBoxStr();
            handles.useCompleteSearchCombo.String = useCompleteSearchStrs;
            handles.useCompleteSearchCombo.Value = PatternSearchUseCompleteSearchEnum.getIndForName(options.useCompleteSearch.name);

            useMeshAccelStrs = PatternSearchUseMeshAccelEnum.getListBoxStr();
            handles.accelMeshCombo.String = useMeshAccelStrs;
            handles.accelMeshCombo.Value = PatternSearchUseMeshAccelEnum.getIndForName(options.accelMesh.name);

            useMeshRotStrs = PatternSearchUseMeshRotationEnum.getListBoxStr();
            handles.useMeshRotateCombo.String = useMeshRotStrs;
            handles.useMeshRotateCombo.Value = PatternSearchUseMeshRotationEnum.getIndForName(options.meshRotate.name);

            pollMethodStrs = PattSrchPollMethodEnum.getListBoxStr();
            handles.pollMethodCombo.String = pollMethodStrs;
            handles.pollMethodCombo.Value = PattSrchPollMethodEnum.getIndForName(options.pollMethod.name);

            pollOrderStrs = PattSrchPollOrderEnum.getListBoxStr();
            handles.pollOrderCombo.String = pollOrderStrs;
            handles.pollOrderCombo.Value = PattSrchPollOrderEnum.getIndForName(options.pollOrder.name);

            searchFuncStrs = PattSrchSearchFcnEnum.getListBoxStr();
            handles.searchFuncCombo.String = searchFuncStrs;
            handles.searchFuncCombo.Value = PattSrchSearchFcnEnum.getIndForName(options.searchFunc.name);

            scaleMeshStrs = PatternSearchUseMeshScalingEnum.getListBoxStr();
            handles.scaleMeshCombo.String = scaleMeshStrs;
            handles.scaleMeshCombo.Value = PatternSearchUseMeshScalingEnum.getIndForName(options.scaleMesh.name);

            useParallelStrs = PatternSearchUseParallelEnum.getListBoxStr();
            handles.useParallelCombo.String = useParallelStrs;
            handles.useParallelCombo.Value = PatternSearchUseParallelEnum.getIndForName(options.useParallel.name);

            setOptsDoubleValueStrInGUI(handles, options, 'cacheTol', 'cacheTolText');
            setOptsDoubleValueStrInGUI(handles, options, 'conTol', 'conTolText');
            setOptsDoubleValueStrInGUI(handles, options, 'funTol', 'funcTolText');
            setOptsDoubleValueStrInGUI(handles, options, 'meshTol', 'meshTolText');
            setOptsDoubleValueStrInGUI(handles, options, 'stepTol', 'stepTolText');

            setOptsDoubleValueStrInGUI(handles, options, 'maxIters', 'maxIterText');
            setOptsDoubleValueStrInGUI(handles, options, 'maxFunEvals', 'maxFunEvalsText');

            setOptsDoubleValueStrInGUI(handles, options, 'cacheSize', 'cacheSizeText');

            setOptsDoubleValueStrInGUI(handles, options, 'initMeshSize', 'initMeshSizeText');
            setOptsDoubleValueStrInGUI(handles, options, 'meshContrFact', 'meshContrFactorText');
            setOptsDoubleValueStrInGUI(handles, options, 'meshExpFact', 'meshExpFactorText');

            setOptsDoubleValueStrInGUI(handles, options, 'initPenalty', 'initPenaltyText');
            setOptsDoubleValueStrInGUI(handles, options, 'penaltyFact', 'penaltyFactText');

            setOptsDoubleValueStrInGUI(handles, options, 'penaltyFact', 'penaltyFactText');

            setOptsDoubleValueStrInGUI(handles, options, 'numWorkers', 'numParaWorkersText');
        end

        %         function setDocLbl(app, handles)
        %                 docLinkLblPos = handles.matlabDocLinkLabel.Position;
        %                 docLinkLblParent = handles.matlabDocLinkLabel.Parent;
        %                 handles.matlabDocLinkLabel.Visible = 'off';
        %
        %                 % Create and display the text label
        %                 url = 'https://www.mathworks.com/help/gads/patternsearch.html';
        %                 labelStr = ['<html><div style=''text-align: center;''>For more information, visit the MATLAB documentation for Pattern Search: <a href="">' url '</a></div></html>'];
        %                 jLabel = javaObjectEDT('javax.swing.JLabel', labelStr);
        %                 [hjLabel,~] = javacomponent(jLabel, docLinkLblPos, docLinkLblParent);
        %
        %                 % Modify the mouse cursor when hovering on the label
        %                 hjLabel.setCursor(java.awt.Cursor.getPredefinedCursor(java.awt.Cursor.HAND_CURSOR));
        %
        %                 % Set the mouse-click callback
        %                 set(hjLabel, 'MouseClickedCallback', @(h,e)web([url], '-browser'));
        %         end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            errMsg = validateDoubleValue(handles, errMsg, 'cacheTolText', 'Cache Tolerance', eps, 1, false);
            errMsg = validateDoubleValue(handles, errMsg, 'conTolText', 'Constraint Tolerance', 1E-12, 1, false);
            errMsg = validateDoubleValue(handles, errMsg, 'funcTolText', 'Function Tolerance', 1E-12, 1, false);
            errMsg = validateDoubleValue(handles, errMsg, 'meshTolText', 'Mesh Tolerance', 1E-12, 1, false);
            errMsg = validateDoubleValue(handles, errMsg, 'stepTolText', 'Step Tolerance', 1E-12, 1, false);

            errMsg = validateDoubleValue(handles, errMsg, 'maxIterText', 'Max Iterations', 0, Inf, true);
            errMsg = validateDoubleValue(handles, errMsg, 'maxFunEvalsText', 'Max Function Evaluations', 0, Inf, true);

            errMsg = validateDoubleValue(handles, errMsg, 'cacheSizeText', 'Cache Size', 1, Inf, true);

            errMsg = validateDoubleValue(handles, errMsg, 'initMeshSizeText', 'Initial Mesh Size', 1E-12, Inf, false);
            errMsg = validateDoubleValue(handles, errMsg, 'meshContrFactorText', 'Mesh Constraction Factor', 1E-12, 1, false);
            errMsg = validateDoubleValue(handles, errMsg, 'meshExpFactorText', 'Mesh Expansion Factor', 1, Inf, false);

            errMsg = validateDoubleValue(handles, errMsg, 'initPenaltyText', 'Initial Penalty', 1, Inf, false);
            errMsg = validateDoubleValue(handles, errMsg, 'penaltyFactText', 'Penalty Factor', 1, Inf, false);

            errMsg = validateDoubleValue(handles, errMsg, 'numParaWorkersText', 'Number of Parallel Workers', 1, feature('numCores'), true);
        end


        function saveAndClose(app, handles)
            patternSearchOpt = getappdata(handles.lvd_editPatternSearchOptionsGUI,'patternSearchOpt');
            options = patternSearchOpt.getOptions();

            useCacheStrs = PatternSearchUseCacheEnum.getListBoxStr();
            options.cache = PatternSearchUseCacheEnum.getEnumForListboxStr(useCacheStrs{handles.useCacheCombo.Value});

            useCompletePollStrs = PatternSearchUseCompletePollEnum.getListBoxStr();
            options.useCompletePoll = PatternSearchUseCompletePollEnum.getEnumForListboxStr(useCompletePollStrs{handles.useCompletePollCombo.Value});

            useCompleteSearchStrs = PatternSearchUseCompleteSearchEnum.getListBoxStr();
            options.useCompleteSearch = PatternSearchUseCompleteSearchEnum.getEnumForListboxStr(useCompleteSearchStrs{handles.useCompleteSearchCombo.Value});

            useMeshAccelStrs = PatternSearchUseMeshAccelEnum.getListBoxStr();
            options.accelMesh = PatternSearchUseMeshAccelEnum.getEnumForListboxStr(useMeshAccelStrs{handles.accelMeshCombo.Value});

            useMeshRotStrs = PatternSearchUseMeshRotationEnum.getListBoxStr();
            options.meshRotate = PatternSearchUseMeshRotationEnum.getEnumForListboxStr(useMeshRotStrs{handles.useMeshRotateCombo.Value});

            pollMethodStrs = PattSrchPollMethodEnum.getListBoxStr();
            options.pollMethod = PattSrchPollMethodEnum.getEnumForListboxStr(pollMethodStrs{handles.pollMethodCombo.Value});

            pollOrderStrs = PattSrchPollOrderEnum.getListBoxStr();
            options.pollOrder = PattSrchPollOrderEnum.getEnumForListboxStr(pollOrderStrs{handles.pollOrderCombo.Value});

            searchFuncStrs = PattSrchSearchFcnEnum.getListBoxStr();
            options.searchFunc = PattSrchSearchFcnEnum.getEnumForListboxStr(searchFuncStrs{handles.searchFuncCombo.Value});

            scaleMeshStrs = PatternSearchUseMeshScalingEnum.getListBoxStr();
            options.scaleMesh = PatternSearchUseMeshScalingEnum.getEnumForListboxStr(scaleMeshStrs{handles.scaleMeshCombo.Value});

            useParallelStrs = PatternSearchUseParallelEnum.getListBoxStr();
            options.useParallel = PatternSearchUseParallelEnum.getEnumForListboxStr(useParallelStrs{handles.useParallelCombo.Value});

            setOptsDoubleValueInObject(handles, options, 'cacheTol', 'cacheTolText');
            setOptsDoubleValueInObject(handles, options, 'conTol', 'conTolText');
            setOptsDoubleValueInObject(handles, options, 'funTol', 'funcTolText');
            setOptsDoubleValueInObject(handles, options, 'meshTol', 'meshTolText');
            setOptsDoubleValueInObject(handles, options, 'stepTol', 'stepTolText');

            setOptsDoubleValueInObject(handles, options, 'maxIters', 'maxIterText');
            setOptsDoubleValueInObject(handles, options, 'maxFunEvals', 'maxFunEvalsText');

            setOptsDoubleValueInObject(handles, options, 'cacheSize', 'cacheSizeText');

            setOptsDoubleValueInObject(handles, options, 'initMeshSize', 'initMeshSizeText');
            setOptsDoubleValueInObject(handles, options, 'meshContrFact', 'meshContrFactorText');
            setOptsDoubleValueInObject(handles, options, 'meshExpFact', 'meshExpFactorText');

            setOptsDoubleValueInObject(handles, options, 'initPenalty', 'initPenaltyText');
            setOptsDoubleValueInObject(handles, options, 'penaltyFact', 'penaltyFactText');

            setOptsDoubleValueInObject(handles, options, 'numWorkers', 'numParaWorkersText');

            app.output.output = {true};
            close(handles.lvd_editPatternSearchOptionsGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_editPatternSearchOptionsGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_editPatternSearchOptionsGUI (see VARARGIN)

            % Choose default command line output for lvd_editPatternSearchOptionsGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            patternSearchOpt = varargin{1};
            setappdata(hObject,'patternSearchOpt',patternSearchOpt);

            app.output = varargin{2};

            handles = populateGUI(app, handles, patternSearchOpt);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_editPatternSearchOptionsGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_editPatternSearchOptionsGUI);
        end

        % Value changed function: cacheSizeText
        function cacheSizeText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cacheSizeText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of cacheSizeText as text
            %        str2double(get(hObject,'String')) returns contents of cacheSizeText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: cacheTolText
        function cacheTolText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cacheTolText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of cacheTolText as text
            %        str2double(get(hObject,'String')) returns contents of cacheTolText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: conTolText
        function conTolText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to conTolText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of conTolText as text
            %        str2double(get(hObject,'String')) returns contents of conTolText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: funcTolText
        function funcTolText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to funcTolText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of funcTolText as text
            %        str2double(get(hObject,'String')) returns contents of funcTolText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: initMeshSizeText
        function initMeshSizeText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to initMeshSizeText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of initMeshSizeText as text
            %        str2double(get(hObject,'String')) returns contents of initMeshSizeText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: initPenaltyText
        function initPenaltyText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to initPenaltyText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of initPenaltyText as text
            %        str2double(get(hObject,'String')) returns contents of initPenaltyText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: maxFunEvalsText
        function maxFunEvalsText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to maxFunEvalsText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of maxFunEvalsText as text
            %        str2double(get(hObject,'String')) returns contents of maxFunEvalsText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: maxIterText
        function maxIterText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to maxIterText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of maxIterText as text
            %        str2double(get(hObject,'String')) returns contents of maxIterText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: meshContrFactorText
        function meshContrFactorText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to meshContrFactorText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of meshContrFactorText as text
            %        str2double(get(hObject,'String')) returns contents of meshContrFactorText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: meshExpFactorText
        function meshExpFactorText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to meshExpFactorText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of meshExpFactorText as text
            %        str2double(get(hObject,'String')) returns contents of meshExpFactorText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: meshTolText
        function meshTolText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to meshTolText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of meshTolText as text
            %        str2double(get(hObject,'String')) returns contents of meshTolText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: penaltyFactText
        function penaltyFactText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to penaltyFactText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of penaltyFactText as text
            %        str2double(get(hObject,'String')) returns contents of penaltyFactText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                %                     uiresume(handles.lvd_editPatternSearchOptionsGUI);
                app.saveAndClose(handles);
            else
                %                     msgbox(errMsg,'Invalid Pattern Search Options Inputs','error');
                uialert(app.lvd_editPatternSearchOptionsGUI, errMsg, 'Invalid Pattern Search Options Inputs', "Icon","error");
            end
        end

        % Value changed function: stepTolText
        function stepTolText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to stepTolText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of stepTolText as text
            %        str2double(get(hObject,'String')) returns contents of stepTolText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_editPatternSearchOptionsGUI = uifigure('Visible', 'off');
            app.lvd_editPatternSearchOptionsGUI.Name = 'Edit Pattern Search Options';
            app.lvd_editPatternSearchOptionsGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_editPatternSearchOptionsGUI.Tag = 'lvd_editPatternSearchOptionsGUI';
            app.GridLayout = uigridlayout(app.lvd_editPatternSearchOptionsGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Pattern Search Options';
            app.saveAndCloseButton = uibutton(app.GridLayout, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout2 = uigridlayout(app.uipanel1);
            app.text20 = uilabel(app.GridLayout2);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Leave a text field blank to use that setting''s default value.';
            app.matlabDocLinkLabel = uilabel(app.GridLayout2);
            app.matlabDocLinkLabel.Tag = 'matlabDocLinkLabel';
            app.matlabDocLinkLabel.Text = 'For more information, visit the MATLAB Pattern Search documentation:';
            app.Hyperlink = uihyperlink(app.GridLayout2);
            app.Hyperlink.URL = 'https://www.mathworks.com/help/gads/patternsearch.html';
            app.Hyperlink.Text = 'https://www.mathworks.com/help/gads/patternsearch.html';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.uipanel2 = uipanel(app.GridLayout4);
            app.uipanel2.Title = 'Tolerances';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout5 = uigridlayout(app.uipanel2);
            app.text5 = uilabel(app.GridLayout5);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Cache Tolerance';
            app.text4 = uilabel(app.GridLayout5);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Constraint Tolerance';
            app.text3 = uilabel(app.GridLayout5);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Function Tolerance';
            app.text6 = uilabel(app.GridLayout5);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Mesh Tolerance';
            app.text7 = uilabel(app.GridLayout5);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Step Tolerance';
            app.cacheTolText = uieditfield(app.GridLayout5, 'text');
            app.cacheTolText.ValueChangedFcn = createCallbackFcn(app, @cacheTolText_Callback, true);
            app.cacheTolText.Tag = 'cacheTolText';
            app.cacheTolText.Tooltip = 'Largest distance from the current mesh point to any point in the history in order for patternsearch to avoid polling the current point. Use if ''Cache'' option is used.';
            app.cacheTolText.Value = '0.0';
            app.conTolText = uieditfield(app.GridLayout5, 'text');
            app.conTolText.ValueChangedFcn = createCallbackFcn(app, @conTolText_Callback, true);
            app.conTolText.Tag = 'conTolText';
            app.conTolText.Tooltip = 'Tolerance on constraints.  Default: 1e-6';
            app.conTolText.Value = '0.0';
            app.funcTolText = uieditfield(app.GridLayout5, 'text');
            app.funcTolText.ValueChangedFcn = createCallbackFcn(app, @funcTolText_Callback, true);
            app.funcTolText.Tag = 'funcTolText';
            app.funcTolText.Tooltip = 'Tolerance on function. Iterations stop if the change in function value is less than FunctionTolerance and the mesh size is less than StepTolerance. Does not apply to MADS polling.';
            app.funcTolText.Value = '0.0';
            app.meshTolText = uieditfield(app.GridLayout5, 'text');
            app.meshTolText.ValueChangedFcn = createCallbackFcn(app, @meshTolText_Callback, true);
            app.meshTolText.Tag = 'meshTolText';
            app.meshTolText.Tooltip = 'Tolerance on mesh size.  Default: 1E-6';
            app.meshTolText.Value = '0.0';
            app.stepTolText = uieditfield(app.GridLayout5, 'text');
            app.stepTolText.ValueChangedFcn = createCallbackFcn(app, @stepTolText_Callback, true);
            app.stepTolText.Tag = 'stepTolText';
            app.stepTolText.Tooltip = 'Tolerance on variable. Iterations stop if both the change in position and the mesh size are less than StepTolerance. Does not apply to MADS polling.';
            app.stepTolText.Value = '0.0';
            app.uipanel4 = uipanel(app.GridLayout4);
            app.uipanel4.Title = 'Maximums (Termination Conditions)';
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout6 = uigridlayout(app.uipanel4);
            app.text10 = uilabel(app.GridLayout6);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Max Iterations';
            app.text9 = uilabel(app.GridLayout6);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Max Function Evals';
            app.maxIterText = uieditfield(app.GridLayout6, 'text');
            app.maxIterText.ValueChangedFcn = createCallbackFcn(app, @maxIterText_Callback, true);
            app.maxIterText.Tag = 'maxIterText';
            app.maxIterText.Tooltip = 'Maximum number of iterations.';
            app.maxIterText.Value = '0.0';
            app.maxFunEvalsText = uieditfield(app.GridLayout6, 'text');
            app.maxFunEvalsText.ValueChangedFcn = createCallbackFcn(app, @maxFunEvalsText_Callback, true);
            app.maxFunEvalsText.Tag = 'maxFunEvalsText';
            app.maxFunEvalsText.Tooltip = 'Maximum number of objective function evaluations.';
            app.maxFunEvalsText.Value = '0.0';
            app.uipanel3 = uipanel(app.GridLayout4);
            app.uipanel3.Title = 'Cache';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout7 = uigridlayout(app.uipanel3);
            app.useCacheCombo = uidropdown(app.GridLayout7);
            app.useCacheCombo.Items = {'Pop-up Menu'};
            app.useCacheCombo.Tag = 'useCacheCombo';
            app.useCacheCombo.Tooltip = 'With Cache is used, patternsearch keeps a history of the mesh points it polls. At subsequent iterations, patternsearch does not poll points close to those it already polled. Use this option if patternsearch runs slowly while computing the objective function. If the objective function is stochastic, do not to use this option.';
            app.useCacheCombo.Value = 'Pop-up Menu';
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.text8 = uilabel(app.GridLayout8);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Cache Size';
            app.cacheSizeText = uieditfield(app.GridLayout8, 'text');
            app.cacheSizeText.ValueChangedFcn = createCallbackFcn(app, @cacheSizeText_Callback, true);
            app.cacheSizeText.Tag = 'cacheSizeText';
            app.cacheSizeText.Tooltip = 'Size of the cache history.';
            app.cacheSizeText.Value = '0.0';
            app.uipanel5 = uipanel(app.GridLayout4);
            app.uipanel5.Title = 'Mesh';
            app.uipanel5.Tag = 'uipanel5';
            app.GridLayout9 = uigridlayout(app.uipanel5);
            app.accelMeshCombo = uidropdown(app.GridLayout9);
            app.accelMeshCombo.Items = {'Pop-up Menu'};
            app.accelMeshCombo.Tag = 'accelMeshCombo';
            app.accelMeshCombo.Tooltip = 'Accelerate convergence near a minimum. patternsearch contracts by an extra factor of 1/2.';
            app.accelMeshCombo.Value = 'Pop-up Menu';
            app.useMeshRotateCombo = uidropdown(app.GridLayout9);
            app.useMeshRotateCombo.Items = {'Pop-up Menu'};
            app.useMeshRotateCombo.Tag = 'useMeshRotateCombo';
            app.useMeshRotateCombo.Tooltip = 'Rotate the pattern before declaring a point to be optimum.';
            app.useMeshRotateCombo.Value = 'Pop-up Menu';
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.text14 = uilabel(app.GridLayout10);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Initial Mesh Size';
            app.text13 = uilabel(app.GridLayout10);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Contraction Factor';
            app.text12 = uilabel(app.GridLayout10);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Expansion Factor';
            app.initMeshSizeText = uieditfield(app.GridLayout10, 'text');
            app.initMeshSizeText.ValueChangedFcn = createCallbackFcn(app, @initMeshSizeText_Callback, true);
            app.initMeshSizeText.Tag = 'initMeshSizeText';
            app.initMeshSizeText.Tooltip = 'Initial mesh size for pattern algorithm.';
            app.initMeshSizeText.Value = '0.0';
            app.meshContrFactorText = uieditfield(app.GridLayout10, 'text');
            app.meshContrFactorText.ValueChangedFcn = createCallbackFcn(app, @meshContrFactorText_Callback, true);
            app.meshContrFactorText.Tag = 'meshContrFactorText';
            app.meshContrFactorText.Tooltip = 'Mesh contraction factor for unsuccessful iteration.';
            app.meshContrFactorText.Value = '0.0';
            app.meshExpFactorText = uieditfield(app.GridLayout10, 'text');
            app.meshExpFactorText.ValueChangedFcn = createCallbackFcn(app, @meshExpFactorText_Callback, true);
            app.meshExpFactorText.Tag = 'meshExpFactorText';
            app.meshExpFactorText.Tooltip = 'Mesh expansion factor for successful iteration.';
            app.meshExpFactorText.Value = '0.0';
            app.GridLayout11 = uigridlayout(app.GridLayout3);
            app.uipanel6 = uipanel(app.GridLayout11);
            app.uipanel6.Title = 'Polling Algorithm';
            app.uipanel6.Tag = 'uipanel6';
            app.GridLayout12 = uigridlayout(app.uipanel6);
            app.pollMethodCombo = uidropdown(app.GridLayout12);
            app.pollMethodCombo.Items = {'Pop-up Menu'};
            app.pollMethodCombo.Tag = 'pollMethodCombo';
            app.pollMethodCombo.Tooltip = 'Polling strategy used in pattern search.';
            app.pollMethodCombo.Value = 'Pop-up Menu';
            app.pollOrderCombo = uidropdown(app.GridLayout12);
            app.pollOrderCombo.Items = {'Pop-up Menu'};
            app.pollOrderCombo.Tag = 'pollOrderCombo';
            app.pollOrderCombo.Tooltip = 'Order of poll directions in pattern search.';
            app.pollOrderCombo.Value = 'Pop-up Menu';
            app.useCompletePollCombo = uidropdown(app.GridLayout12);
            app.useCompletePollCombo.Items = {'Pop-up Menu'};
            app.useCompletePollCombo.Tag = 'useCompletePollCombo';
            app.useCompletePollCombo.Tooltip = 'Complete poll around current point.';
            app.useCompletePollCombo.Value = 'Pop-up Menu';
            app.uipanel7 = uipanel(app.GridLayout11);
            app.uipanel7.Title = 'Search Algorithm';
            app.uipanel7.Tag = 'uipanel7';
            app.GridLayout13 = uigridlayout(app.uipanel7);
            app.searchFuncCombo = uidropdown(app.GridLayout13);
            app.searchFuncCombo.Items = {'Pop-up Menu'};
            app.searchFuncCombo.Tag = 'searchFuncCombo';
            app.searchFuncCombo.Tooltip = 'Type of search used in pattern search.';
            app.searchFuncCombo.Value = 'Pop-up Menu';
            app.scaleMeshCombo = uidropdown(app.GridLayout13);
            app.scaleMeshCombo.Items = {'Pop-up Menu'};
            app.scaleMeshCombo.Tag = 'scaleMeshCombo';
            app.scaleMeshCombo.Tooltip = 'Automatic scaling of variables.';
            app.scaleMeshCombo.Value = 'Pop-up Menu';
            app.useCompleteSearchCombo = uidropdown(app.GridLayout13);
            app.useCompleteSearchCombo.Items = {'Pop-up Menu'};
            app.useCompleteSearchCombo.Tag = 'useCompleteSearchCombo';
            app.useCompleteSearchCombo.Tooltip = 'Complete search around current point when the search method is a poll method';
            app.useCompleteSearchCombo.Value = 'Pop-up Menu';
            app.uipanel8 = uipanel(app.GridLayout11);
            app.uipanel8.Title = 'Penalty';
            app.uipanel8.Tag = 'uipanel8';
            app.GridLayout14 = uigridlayout(app.uipanel8);
            app.text18 = uilabel(app.GridLayout14);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Initial Penalty';
            app.text17 = uilabel(app.GridLayout14);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Penalty Factor';
            app.initPenaltyText = uieditfield(app.GridLayout14, 'text');
            app.initPenaltyText.ValueChangedFcn = createCallbackFcn(app, @initPenaltyText_Callback, true);
            app.initPenaltyText.Tag = 'initPenaltyText';
            app.initPenaltyText.Tooltip = 'Initial value of the penalty parameter.';
            app.initPenaltyText.Value = '0.0';
            app.penaltyFactText = uieditfield(app.GridLayout14, 'text');
            app.penaltyFactText.ValueChangedFcn = createCallbackFcn(app, @penaltyFactText_Callback, true);
            app.penaltyFactText.Tag = 'penaltyFactText';
            app.penaltyFactText.Tooltip = 'Penalty update parameter.';
            app.penaltyFactText.Value = '0.0';
            app.uipanel9 = uipanel(app.GridLayout11);
            app.uipanel9.Title = 'Parallelization';
            app.uipanel9.Tag = 'uipanel9';
            app.GridLayout15 = uigridlayout(app.uipanel9);
            app.useParallelCombo = uidropdown(app.GridLayout15);
            app.useParallelCombo.Items = {'Pop-up Menu'};
            app.useParallelCombo.Tag = 'useParallelCombo';
            app.useParallelCombo.Tooltip = 'If in use, computes the objective and nonlinear constraint functions in parallel.';
            app.useParallelCombo.Value = 'Pop-up Menu';
            app.GridLayout16 = uigridlayout(app.GridLayout15);
            app.text21 = uilabel(app.GridLayout16);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Num. Parallel Workers';
            app.numParaWorkersText = uieditfield(app.GridLayout16, 'text');
            app.numParaWorkersText.Tag = 'numParaWorkersText';
            app.numParaWorkersText.Tooltip = 'The number of parallel workers to use for computing the values.';
            app.numParaWorkersText.Value = '0.0';
            % Show the figure after all components are created
            app.lvd_editPatternSearchOptionsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_editPatternSearchOptionsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_editPatternSearchOptionsGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_editPatternSearchOptionsGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_editPatternSearchOptionsGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_editPatternSearchOptionsGUI)
        end
    end
end