classdef lvd_editSqpOptOptionsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditSQPOptimizerOptionsUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        saveAndCloseButton              matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        text26                          matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        matlabDocLinkLabel              matlab.ui.control.Label
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        MiscOptionsPanel                matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        MaxLineSearchIterationsEditField  matlab.ui.control.NumericEditField
        MaxLineSearchIterationsEditFieldLabel  matlab.ui.control.Label
        TerminationCriteriaPanel        matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        MaxIterationsEditField          matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel     matlab.ui.control.Label
        MaxFunctionEvalsEditField       matlab.ui.control.NumericEditField
        MaxFunctionEvalsEditFieldLabel  matlab.ui.control.Label
        OptimalityCriteriaDropDown      matlab.ui.control.DropDown
        OptimalityCriteriaDropDownLabel  matlab.ui.control.Label
        GridLayout5                     matlab.ui.container.GridLayout
        ParallelizationPanel            matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        NumParallelWorkersEditField     matlab.ui.control.NumericEditField
        NumParallelWorkersEditFieldLabel  matlab.ui.control.Label
        UseParallelizationDropDown      matlab.ui.control.DropDown
        UseParallelizationDropDownLabel  matlab.ui.control.Label
        BuiltInFiniteDifferencesPanel   matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        StepSizeEditField               matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel          matlab.ui.control.Label
        TolerancesPanel                 matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        ConstraintToleranceEditField    matlab.ui.control.NumericEditField
        ConstraintToleranceEditFieldLabel  matlab.ui.control.Label
        OptimalityToleranceEditField    matlab.ui.control.NumericEditField
        OptimalityToleranceEditFieldLabel  matlab.ui.control.Label
        StepToleranceEditField          matlab.ui.control.NumericEditField
        StepToleranceEditFieldLabel     matlab.ui.control.Label
        text2                           matlab.ui.control.Label
    end

    properties (Access = private)
        options SqpOptions
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app)
            app.StepToleranceEditField.Value = app.convertNaNToEmpty(app.options.stepTol);
            app.OptimalityToleranceEditField.Value = app.convertNaNToEmpty(app.options.optTol);
            app.ConstraintToleranceEditField.Value = app.convertNaNToEmpty(app.options.tolCon);

            app.StepSizeEditField.Value = app.convertNaNToEmpty(app.options.finDiffStepSize);

            [listboxStr, m] = SqpUseParallelEnum.getListBoxStr();
            app.UseParallelizationDropDown.Items = listboxStr;
            app.UseParallelizationDropDown.ItemsData = m;
            app.UseParallelizationDropDown.Value = app.options.useParallel;

            app.NumParallelWorkersEditField.Value = app.convertNaNToEmpty(app.options.numWorkers);

            [listBoxStr, m] = SqpTerminationCondEnum.getListBoxStr();
            app.OptimalityCriteriaDropDown.Items = listBoxStr;
            app.OptimalityCriteriaDropDown.ItemsData = m;
            app.OptimalityCriteriaDropDown.Value = app.options.terminationType;

            app.MaxFunctionEvalsEditField.Value = app.convertNaNToEmpty(app.options.maxFuncEvals);
            app.MaxIterationsEditField.Value = app.convertNaNToEmpty(app.options.maxIter);

            app.MaxLineSearchIterationsEditField.Value = app.convertNaNToEmpty(app.options.maxLineSearchFun);
        end
        
        function numOut = convertNaNToEmpty(~, num)
            numOut = [];
            if(not(isnan(num)))
                numOut = num;
            end
        end
        
        function saveAndClose(app)
            if(not(isempty(app.StepToleranceEditField.Value)))
                app.options.stepTol = app.StepToleranceEditField.Value;
            else
                app.options.stepTol = NaN;
            end

            if(not(isempty(app.OptimalityToleranceEditField.Value)))
                app.options.optTol = app.OptimalityToleranceEditField.Value;
            else
                app.options.optTol = NaN;
            end

            if(not(isempty(app.ConstraintToleranceEditField.Value)))
                app.options.tolCon = app.ConstraintToleranceEditField.Value;
            else
                app.options.tolCon = NaN;
            end

            if(not(isempty(app.StepSizeEditField.Value)))
                app.options.finDiffStepSize = app.StepSizeEditField.Value;
            else
                app.options.finDiffStepSize = NaN;
            end
            
            app.options.useParallel = app.UseParallelizationDropDown.Value;
            app.options.numWorkers = app.NumParallelWorkersEditField.Value;

            app.options.terminationType = app.OptimalityCriteriaDropDown.Value;

            if(not(isempty(app.MaxFunctionEvalsEditField.Value)))
                app.options.maxFuncEvals = app.MaxFunctionEvalsEditField.Value;
            else
                app.options.maxFuncEvals = NaN;
            end

            if(not(isempty(app.MaxIterationsEditField.Value)))
                app.options.maxIter = app.MaxIterationsEditField.Value;
            else
                app.options.maxIter = NaN;
            end

            app.output.output = {true};
            close(app.EditSQPOptimizerOptionsUIFigure);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, options, output)
            centerUIFigure(app.EditSQPOptimizerOptionsUIFigure);
            applySelectedThemeToApp(app);

            app.options = options;
            app.output = output;

            app.populateGUI();
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButtonPushed(app, event)
            % errMsg = validateInputs(app);
            errMsg = {};

            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.EditSQPOptimizerOptionsUIFigure, errMsg, 'Invalid SQP Options Inputs', "Icon","error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditSQPOptimizerOptionsUIFigure = uifigure('Visible', 'off');
            app.EditSQPOptimizerOptionsUIFigure.Name = 'Edit SQP Optimizer Options';
            app.EditSQPOptimizerOptionsUIFigure.Icon = 'ksptot_logo4_transparent.png';
            app.GridLayout = uigridlayout(app.EditSQPOptimizerOptionsUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit SQP Optimizer Options';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.TolerancesPanel = uipanel(app.GridLayout5);
            app.TolerancesPanel.Title = 'Tolerances';
            app.GridLayout6 = uigridlayout(app.TolerancesPanel);
            app.StepToleranceEditFieldLabel = uilabel(app.GridLayout6);
            app.StepToleranceEditFieldLabel.Text = 'Step Tolerance';
            app.StepToleranceEditField = uieditfield(app.GridLayout6, 'numeric');
            app.StepToleranceEditField.Limits = [1e-10 1];
            app.StepToleranceEditField.AllowEmpty = 'on';
            app.StepToleranceEditField.Tooltip = {'Termination tolerance on x, a positive scalar. The default value is 1e-6.'};
            app.StepToleranceEditField.Value = [];
            app.OptimalityToleranceEditFieldLabel = uilabel(app.GridLayout6);
            app.OptimalityToleranceEditFieldLabel.Text = 'Optimality Tolerance';
            app.OptimalityToleranceEditField = uieditfield(app.GridLayout6, 'numeric');
            app.OptimalityToleranceEditField.Limits = [1e-10 1];
            app.OptimalityToleranceEditField.AllowEmpty = 'on';
            app.OptimalityToleranceEditField.Tooltip = {'Termination tolerance on the first-order optimality'; ' a positive scalar. The default is 1e-6.'};
            app.OptimalityToleranceEditField.Value = [];
            app.ConstraintToleranceEditFieldLabel = uilabel(app.GridLayout6);
            app.ConstraintToleranceEditFieldLabel.Text = 'Constraint Tolerance';
            app.ConstraintToleranceEditField = uieditfield(app.GridLayout6, 'numeric');
            app.ConstraintToleranceEditField.Limits = [1e-10 1];
            app.ConstraintToleranceEditField.AllowEmpty = 'on';
            app.ConstraintToleranceEditField.Tooltip = {'Tolerance on the constraint violation, a positive scalar. The default is 1e-6. '};
            app.ConstraintToleranceEditField.Value = [];
            app.BuiltInFiniteDifferencesPanel = uipanel(app.GridLayout5);
            app.BuiltInFiniteDifferencesPanel.Title = 'Built-In Finite Differences';
            app.GridLayout9 = uigridlayout(app.BuiltInFiniteDifferencesPanel);
            app.StepSizeEditFieldLabel = uilabel(app.GridLayout9);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.GridLayout9, 'numeric');
            app.StepSizeEditField.Limits = [1e-10 0.1];
            app.StepSizeEditField.AllowEmpty = 'on';
            app.StepSizeEditField.Tooltip = {'Step size factor for built-in finite differences. '};
            app.StepSizeEditField.Value = [];
            app.ParallelizationPanel = uipanel(app.GridLayout5);
            app.ParallelizationPanel.Title = 'Parallelization';
            app.GridLayout11 = uigridlayout(app.ParallelizationPanel);
            app.UseParallelizationDropDownLabel = uilabel(app.GridLayout11);
            app.UseParallelizationDropDownLabel.Text = 'Use Parallelization';
            app.UseParallelizationDropDown = uidropdown(app.GridLayout11);
            app.UseParallelizationDropDown.Tooltip = {'When selected, SQP estimates gradients in parallel.  Parallel calculation of gradients can speed up the optimization.'};
            app.NumParallelWorkersEditFieldLabel = uilabel(app.GridLayout11);
            app.NumParallelWorkersEditFieldLabel.Text = 'Num. Parallel Workers';
            app.NumParallelWorkersEditField = uieditfield(app.GridLayout11, 'numeric');
            app.NumParallelWorkersEditField.Limits = [1 Inf];
            app.NumParallelWorkersEditField.RoundFractionalValues = 'on';
            app.NumParallelWorkersEditField.ValueDisplayFormat = '%.0f';
            app.NumParallelWorkersEditField.Tooltip = {'The number of parallel workers to use for computing the gradients.'};
            app.NumParallelWorkersEditField.Value = 1;
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.TerminationCriteriaPanel = uipanel(app.GridLayout7);
            app.TerminationCriteriaPanel.Title = 'Termination Criteria';
            app.GridLayout8 = uigridlayout(app.TerminationCriteriaPanel);
            app.OptimalityCriteriaDropDownLabel = uilabel(app.GridLayout8);
            app.OptimalityCriteriaDropDownLabel.Text = 'Optimality Criteria';
            app.OptimalityCriteriaDropDown = uidropdown(app.GridLayout8);
            app.OptimalityCriteriaDropDown.Tooltip = {'Dictates the optimality condition(s) by which the optimizer will stop.  See documentation for more details.'};
            app.MaxFunctionEvalsEditFieldLabel = uilabel(app.GridLayout8);
            app.MaxFunctionEvalsEditFieldLabel.Text = 'Max. Function Evals';
            app.MaxFunctionEvalsEditField = uieditfield(app.GridLayout8, 'numeric');
            app.MaxFunctionEvalsEditField.Limits = [1 Inf];
            app.MaxFunctionEvalsEditField.RoundFractionalValues = 'on';
            app.MaxFunctionEvalsEditField.ValueDisplayFormat = '%.0f';
            app.MaxFunctionEvalsEditField.AllowEmpty = 'on';
            app.MaxFunctionEvalsEditField.Tooltip = {'Maximum number of function evaluations allowed, a positive integer.'};
            app.MaxFunctionEvalsEditField.Value = [];
            app.MaxIterationsEditFieldLabel = uilabel(app.GridLayout8);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.GridLayout8, 'numeric');
            app.MaxIterationsEditField.Limits = [1 Inf];
            app.MaxIterationsEditField.RoundFractionalValues = 'on';
            app.MaxIterationsEditField.ValueDisplayFormat = '%.0f';
            app.MaxIterationsEditField.AllowEmpty = 'on';
            app.MaxIterationsEditField.Tooltip = {'Maximum number of iterations allowed, a positive integer.'};
            app.MaxIterationsEditField.Value = [];
            app.MiscOptionsPanel = uipanel(app.GridLayout7);
            app.MiscOptionsPanel.Title = 'Misc. Options';
            app.GridLayout10 = uigridlayout(app.MiscOptionsPanel);
            app.MaxLineSearchIterationsEditFieldLabel = uilabel(app.GridLayout10);
            app.MaxLineSearchIterationsEditFieldLabel.Text = 'Max Line Search Iterations';
            app.MaxLineSearchIterationsEditField = uieditfield(app.GridLayout10, 'numeric');
            app.MaxLineSearchIterationsEditField.Limits = [1 Inf];
            app.MaxLineSearchIterationsEditField.RoundFractionalValues = 'on';
            app.MaxLineSearchIterationsEditField.ValueDisplayFormat = '%.0f';
            app.MaxLineSearchIterationsEditField.AllowEmpty = 'on';
            app.MaxLineSearchIterationsEditField.Tooltip = {'The number of iterations allowed in the inner line search step.'};
            app.MaxLineSearchIterationsEditField.Value = [];
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.matlabDocLinkLabel = uilabel(app.GridLayout3);
            app.matlabDocLinkLabel.Tag = 'matlabDocLinkLabel';
            app.matlabDocLinkLabel.Text = 'For more information, visit the SQP documentation:';
            app.Hyperlink = uihyperlink(app.GridLayout3);
            app.Hyperlink.URL = 'sqp.pdf';
            app.Hyperlink.Text = 'sqp.pdf';
            app.text26 = uilabel(app.GridLayout3);
            app.text26.Tag = 'text26';
            app.text26.Text = 'Leave a text field blank to use that setting''s default value.';
            app.saveAndCloseButton = uibutton(app.GridLayout, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButtonPushed, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            % Show the figure after all components are created
            app.EditSQPOptimizerOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_editSqpOptOptionsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditSQPOptimizerOptionsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditSQPOptimizerOptionsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditSQPOptimizerOptionsUIFigure)
        end
    end
end