classdef lvd_editSurrogateOptOptionsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_editSurrogateOptOptionsGUI  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        uipanel9                        matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        uipanel3                        matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        maxFuncEvalsText                matlab.ui.control.EditField
        text7                           matlab.ui.control.Label
        uipanel2                        matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        conTolText                      matlab.ui.control.EditField
        text4                           matlab.ui.control.Label
        uipanel11                       matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        minSurrogatePointsText          matlab.ui.control.EditField
        minSampleDistanceText           matlab.ui.control.EditField
        batchUpdateIntervalText         matlab.ui.control.EditField
        text28                          matlab.ui.control.Label
        text29                          matlab.ui.control.Label
        text30                          matlab.ui.control.Label
        uipanel10                       matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        numParaWorkersText              matlab.ui.control.EditField
        text27                          matlab.ui.control.Label
        useParallelCombo                matlab.ui.control.DropDown
        text26                          matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        matlabDocLinkLabel              matlab.ui.control.Label
        saveAndCloseButton              matlab.ui.control.Button
        text2                           matlab.ui.control.Label
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput
    end
    
    methods (Access = private)
        function handles = populateGUI(app, handles, surrogateOpt)
                options = surrogateOpt.getOptions();
            
%                 setDocLbl(app, handles);
            
                useParaStr = SurrogateOptUseParallelEnum.getListBoxStr();
                handles.useParallelCombo.String = useParaStr;
                handles.useParallelCombo.Value = SurrogateOptUseParallelEnum.getIndForName(options.useParallel.name);
            
                setOptsDoubleValueStrInGUI(handles, options, 'tolCon', 'conTolText');
            
                setOptsDoubleValueStrInGUI(handles, options, 'maxFuncEvals', 'maxFuncEvalsText');
            
                setOptsDoubleValueStrInGUI(handles, options, 'batchUpdateInterval', 'batchUpdateIntervalText');
                setOptsDoubleValueStrInGUI(handles, options, 'minSampleDistance', 'minSampleDistanceText');
                setOptsDoubleValueStrInGUI(handles, options, 'minSurrogatePoints', 'minSurrogatePointsText');
            
                setOptsDoubleValueStrInGUI(handles, options, 'numWorkers', 'numParaWorkersText');
        end
        
%         function setDocLbl(app, handles)
%                 docLinkLblPos = handles.matlabDocLinkLabel.Position;
%                 docLinkLblParent = handles.matlabDocLinkLabel.Parent;
%                 handles.matlabDocLinkLabel.Visible = 'off';
%             
%                 % Create and display the text label
%                 url = 'https://www.mathworks.com/help/gads/surrogate-optimization.html';
%                 labelStr = ['<html><div style=''text-align: center;''>For more information, visit the MATLAB documentation for Surrogate Optimizer: <a href="">' url '</a></div></html>'];
%                 jLabel = javaObjectEDT('javax.swing.JLabel', labelStr);
%                 [hjLabel,~] = javacomponent(jLabel, docLinkLblPos, docLinkLblParent);
%             
%                 % Modify the mouse cursor when hovering on the label
%                 hjLabel.setCursor(java.awt.Cursor.getPredefinedCursor(java.awt.Cursor.HAND_CURSOR));
%             
%                 % Set the mouse-click callback
%                 set(hjLabel, 'MouseClickedCallback', @(h,e)web([url], '-browser'));
%         end
        
        function errMsg = validateInputs(app, handles)
                errMsg = {};
            
                errMsg = validateDoubleValue(handles, errMsg, 'conTolText', 'Constraint Tolerance', 0, 1, false);
            
                errMsg = validateDoubleValue(handles, errMsg, 'maxFuncEvalsText', 'Max Function Evaluations', 0, Inf, true);
            
            	errMsg = validateDoubleValue(handles, errMsg, 'batchUpdateIntervalText', 'Batch Update Interval', 1, Inf, true);
                errMsg = validateDoubleValue(handles, errMsg, 'minSampleDistanceText', 'Minimum Sample Distance', 1E-10, Inf, false);
                errMsg = validateDoubleValue(handles, errMsg, 'minSurrogatePointsText', 'Minimum Number of Surrogate Points', 1, Inf, true);
            
                errMsg = validateDoubleValue(handles, errMsg, 'numParaWorkersText', 'Number of Parallel Workers', 1, feature('numCores'), true);
        end
        
        
        function saveAndClose(app, handles)
        surrogateOpt = getappdata(handles.lvd_editSurrogateOptOptionsGUI,'surrogateOpt');
        options = surrogateOpt.getOptions();
        
        useParaStrs = SurrogateOptUseParallelEnum.getListBoxStr();
        options.useParallel = SurrogateOptUseParallelEnum.getEnumForListboxStr(useParaStrs{handles.useParallelCombo.Value});
        
        setOptsDoubleValueInObject(handles, options, 'tolCon', 'conTolText');

        setOptsDoubleValueInObject(handles, options, 'maxFuncEvals', 'maxFuncEvalsText');

        setOptsDoubleValueInObject(handles, options, 'batchUpdateInterval', 'batchUpdateIntervalText');
        setOptsDoubleValueInObject(handles, options, 'minSampleDistance', 'minSampleDistanceText');
        setOptsDoubleValueInObject(handles, options, 'minSurrogatePoints', 'minSurrogatePointsText');
        
        setOptsDoubleValueInObject(handles, options, 'numWorkers', 'numParaWorkersText');
        
        app.output.output = {true};
        close(handles.lvd_editSurrogateOptOptionsGUI);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_editSurrogateOptOptionsGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_editSurrogateOptOptionsGUI (see VARARGIN)
            
            % Choose default command line output for lvd_editSurrogateOptOptionsGUI
                handles.output = hObject;
            
                centerUIFigure(hObject);
                applySelectedThemeToApp(app);
            
                surrogateOpt = varargin{1};
                setappdata(hObject,'surrogateOpt',surrogateOpt);
                
                app.output = varargin{2};
            
                handles = populateGUI(app, handles, surrogateOpt);
            
                % Update handles structure
                guidata(hObject, handles);
            
                % UIWAIT makes lvd_editSurrogateOptOptionsGUI wait for user response (see UIRESUME)
                uiwait(handles.lvd_editSurrogateOptOptionsGUI);
        end

        % Value changed function: batchUpdateIntervalText
        function batchUpdateIntervalText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to batchUpdateIntervalText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of batchUpdateIntervalText as text
            %        str2double(get(hObject,'String')) returns contents of batchUpdateIntervalText as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
        end

        % Value changed function: conTolText
        function conTolText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to conTolText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of conTolText as text
            %        str2double(get(hObject,'String')) returns contents of conTolText as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
        end

        % Value changed function: maxFuncEvalsText
        function maxFuncEvalsText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to maxFuncEvalsText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of maxFuncEvalsText as text
            %        str2double(get(hObject,'String')) returns contents of maxFuncEvalsText as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
        end

        % Value changed function: minSampleDistanceText
        function minSampleDistanceText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to minSampleDistanceText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of minSampleDistanceText as text
            %        str2double(get(hObject,'String')) returns contents of minSampleDistanceText as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
        end

        % Value changed function: minSurrogatePointsText
        function minSurrogatePointsText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to minSurrogatePointsText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of minSurrogatePointsText as text
            %        str2double(get(hObject,'String')) returns contents of minSurrogatePointsText as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
        end

        % Value changed function: numParaWorkersText
        function numParaWorkersText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to numParaWorkersText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of numParaWorkersText as text
            %        str2double(get(hObject,'String')) returns contents of numParaWorkersText as a double
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                errMsg = validateInputs(app, handles);
            
                if(isempty(errMsg))
%                     uiresume(handles.lvd_editSurrogateOptOptionsGUI);
                    app.saveAndClose(handles);
                else
%                     msgbox(errMsg,'Invalid SURROGATEOPT Options Inputs','error');
                    uialert(app.lvd_editSurrogateOptOptionsGUI, errMsg, 'Invalid SURROGATEOPT Options Inputs', "Icon","error");
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_editSurrogateOptOptionsGUI = uifigure('Visible', 'off');
            app.lvd_editSurrogateOptOptionsGUI.Name = 'Edit SURROGATEOPT Options';
            app.lvd_editSurrogateOptOptionsGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_editSurrogateOptOptionsGUI.Tag = 'lvd_editSurrogateOptOptionsGUI';
            app.GridLayout = uigridlayout(app.lvd_editSurrogateOptOptionsGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Surrogate Optimizer Options';
            app.saveAndCloseButton = uibutton(app.GridLayout, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.uipanel9 = uipanel(app.GridLayout);
            app.uipanel9.Tag = 'uipanel9';
            app.GridLayout2 = uigridlayout(app.uipanel9);
            app.matlabDocLinkLabel = uilabel(app.GridLayout2);
            app.matlabDocLinkLabel.Tag = 'matlabDocLinkLabel';
            app.matlabDocLinkLabel.Text = 'For more information, visit the MATLAB Surrogate Optimizer documentation:';
            app.Hyperlink = uihyperlink(app.GridLayout2);
            app.Hyperlink.URL = 'https://www.mathworks.com/help/gads/surrogate-optimization.html';
            app.Hyperlink.Text = 'https://www.mathworks.com/help/gads/surrogate-optimization.html';
            app.text26 = uilabel(app.GridLayout2);
            app.text26.Tag = 'text26';
            app.text26.Text = 'Leave a text field blank to use that setting''s default value.';
            app.uipanel10 = uipanel(app.GridLayout2);
            app.uipanel10.Title = 'Parallelization';
            app.uipanel10.Tag = 'uipanel10';
            app.GridLayout3 = uigridlayout(app.uipanel10);
            app.useParallelCombo = uidropdown(app.GridLayout3);
            app.useParallelCombo.Items = {'Pop-up Menu'};
            app.useParallelCombo.Tag = 'useParallelCombo';
            app.useParallelCombo.Tooltip = 'Boolean value indicating whether to compute objective function values in parallel.';
            app.useParallelCombo.Value = 'Pop-up Menu';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.text27 = uilabel(app.GridLayout4);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Num. Parallel Workers';
            app.numParaWorkersText = uieditfield(app.GridLayout4, 'text');
            app.numParaWorkersText.ValueChangedFcn = createCallbackFcn(app, @numParaWorkersText_Callback, true);
            app.numParaWorkersText.Tag = 'numParaWorkersText';
            app.numParaWorkersText.Tooltip = 'The number of parallel workers to use for computing objective function values.  Must be an integer between and the number of physical cores available.';
            app.numParaWorkersText.Value = '0.0';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.uipanel11 = uipanel(app.GridLayout5);
            app.uipanel11.Title = 'Tolerances';
            app.uipanel11.Tag = 'uipanel11';
            app.GridLayout6 = uigridlayout(app.uipanel11);
            app.text30 = uilabel(app.GridLayout6);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Batch Update Interval';
            app.text29 = uilabel(app.GridLayout6);
            app.text29.Tag = 'text29';
            app.text29.Text = 'Min Sample Distance';
            app.text28 = uilabel(app.GridLayout6);
            app.text28.Tag = 'text28';
            app.text28.Text = 'Min Surrogate Points';
            app.batchUpdateIntervalText = uieditfield(app.GridLayout6, 'text');
            app.batchUpdateIntervalText.ValueChangedFcn = createCallbackFcn(app, @batchUpdateIntervalText_Callback, true);
            app.batchUpdateIntervalText.Tag = 'batchUpdateIntervalText';
            app.batchUpdateIntervalText.Tooltip = 'Number of function evaluations before the surrogate is updated.  Default is 1.';
            app.batchUpdateIntervalText.Value = '0.0';
            app.minSampleDistanceText = uieditfield(app.GridLayout6, 'text');
            app.minSampleDistanceText.ValueChangedFcn = createCallbackFcn(app, @minSampleDistanceText_Callback, true);
            app.minSampleDistanceText.Tag = 'minSampleDistanceText';
            app.minSampleDistanceText.Tooltip = 'Minimum distance between trial points generated by the adaptive sampler.  Default is 1E-3.';
            app.minSampleDistanceText.Value = '0.0';
            app.minSurrogatePointsText = uieditfield(app.GridLayout6, 'text');
            app.minSurrogatePointsText.ValueChangedFcn = createCallbackFcn(app, @minSurrogatePointsText_Callback, true);
            app.minSurrogatePointsText.Tag = 'minSurrogatePointsText';
            app.minSurrogatePointsText.Tooltip = 'Minimum number of random sample points to create at the start of a surrogate creation phase.  Default is max(20,2*nvar), where nvar is the number of problem variables.';
            app.minSurrogatePointsText.Value = '0.0';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.uipanel2 = uipanel(app.GridLayout7);
            app.uipanel2.Title = 'Tolerances';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout8 = uigridlayout(app.uipanel2);
            app.text4 = uilabel(app.GridLayout8);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Constraint Tolerance';
            app.conTolText = uieditfield(app.GridLayout8, 'text');
            app.conTolText.ValueChangedFcn = createCallbackFcn(app, @conTolText_Callback, true);
            app.conTolText.Tag = 'conTolText';
            app.conTolText.Tooltip = 'Tolerance on nonlinear constraints, measured as the maximum of all nonlinear constraint function values, where positive values indicate a violation. This tolerance is an absolute (not relative) tolerance.  The default is 1E-3.';
            app.conTolText.Value = '0.0';
            app.uipanel3 = uipanel(app.GridLayout7);
            app.uipanel3.Title = 'Maximums (Termination Conditions)';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout9 = uigridlayout(app.uipanel3);
            app.text7 = uilabel(app.GridLayout9);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Max Function Evals';
            app.maxFuncEvalsText = uieditfield(app.GridLayout9, 'text');
            app.maxFuncEvalsText.ValueChangedFcn = createCallbackFcn(app, @maxFuncEvalsText_Callback, true);
            app.maxFuncEvalsText.Tag = 'maxFuncEvalsText';
            app.maxFuncEvalsText.Tooltip = 'Maximum number of objective function evaluations, a stopping criterion.  Default is max(200,50*nvar), where nvar is the number of problem variables.';
            app.maxFuncEvalsText.Value = '0.0';
            % Show the figure after all components are created
            app.lvd_editSurrogateOptOptionsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_editSurrogateOptOptionsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_editSurrogateOptOptionsGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_editSurrogateOptOptionsGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_editSurrogateOptOptionsGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_editSurrogateOptOptionsGUI)
        end
    end
end