classdef lvd_finiteDiffOptionsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_finiteDiffOptionsGUI  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        uipanel1                  matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        GridLayout4               matlab.ui.container.GridLayout
        numPointsText             matlab.ui.control.EditField
        text4                     matlab.ui.control.Label
        GridLayout3               matlab.ui.container.GridLayout
        pertStepHText             matlab.ui.control.EditField
        text3                     matlab.ui.control.Label
        finDiffTypeCombo          matlab.ui.control.DropDown
        computeSparsityCheckbox   matlab.ui.control.CheckBox
        saveAndCloseButton        matlab.ui.control.Button
        text2                     matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function handles = populateGUI(app, handles, finDiffObj)
            handles.pertStepHText.String = fullAccNum2Str(finDiffObj.h);
            handles.numPointsText.String = fullAccNum2Str(double(finDiffObj.numPts));

            typeListboxStr = FiniteDiffTypeEnum.getListBoxStr();
            handles.finDiffTypeCombo.String = typeListboxStr;
            handles.finDiffTypeCombo.Value = FiniteDiffTypeEnum.getIndForName(finDiffObj.diffType.name);

            if(finDiffObj.shouldComputeSparsity())
                handles.computeSparsityCheckbox.Value = 1;
            else
                handles.computeSparsityCheckbox.Value = 0;
            end
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            errMsg = validateDoubleValue(handles, errMsg, 'pertStepHText', 'Normalized Perturbation Step', 1E-10, 1E-1, false);
            errMsg = validateDoubleValue(handles, errMsg, 'numPointsText', 'Number of Points', 2, Inf, true);
        end


        function saveAndClose(app, handles)
            finDiffObj = getappdata(handles.lvd_finiteDiffOptionsGUI,'finDiffObj');

            hVal = str2double(handles.pertStepHText.String);
            if(~isnan(hVal) && hVal >= 1E-10 && hVal <= 1E-1)
                finDiffObj.h = hVal;
            end

            numPtsVal = str2double(handles.numPointsText.String);
            if(~isnan(numPtsVal) && numPtsVal >= 2 && round(numPtsVal) == numPtsVal)
                finDiffObj.numPts = numPtsVal;
            end

            typeListboxStr = FiniteDiffTypeEnum.getListBoxStr();
            finDiffObj.diffType = FiniteDiffTypeEnum.getEnumForListboxStr(typeListboxStr{handles.finDiffTypeCombo.Value});

            computeSparsity = logical(handles.computeSparsityCheckbox.Value);
            finDiffObj.computeSparsity = computeSparsity;

            app.output.output = {true};
            close(handles.lvd_finiteDiffOptionsGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_finiteDiffOptionsGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_finiteDiffOptionsGUI (see VARARGIN)

            % Choose default command line output for lvd_finiteDiffOptionsGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            finDiffObj = varargin{1};
            setappdata(hObject,'finDiffObj',finDiffObj);
            
            app.output = varargin{2};

            populateGUI(app, handles, finDiffObj);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_finiteDiffOptionsGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_finiteDiffOptionsGUI);
        end

        % Value changed function: numPointsText
        function numPointsText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to numPointsText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of numPointsText as text
            %        str2double(get(hObject,'String')) returns contents of numPointsText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: pertStepHText
        function pertStepHText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pertStepHText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of pertStepHText as text
            %        str2double(get(hObject,'String')) returns contents of pertStepHText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
%                 uiresume(handles.lvd_finiteDiffOptionsGUI);
                app.saveAndClose(handles);
            else
%                 msgbox(errMsg,'Invalid Finite Differences Options Inputs','error');
                uialert(app.lvd_finiteDiffOptionsGUI, errMsg, 'Invalid Finite Differences Options Inputs', "Icon","error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_finiteDiffOptionsGUI = uifigure('Visible', 'off');
            app.lvd_finiteDiffOptionsGUI.Name = 'Edit Finite Differences Options';
            app.lvd_finiteDiffOptionsGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_finiteDiffOptionsGUI.Tag = 'lvd_finiteDiffOptionsGUI';
            app.GridLayout = uigridlayout(app.lvd_finiteDiffOptionsGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Finite Differences Options';
            app.saveAndCloseButton = uibutton(app.GridLayout, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout2 = uigridlayout(app.uipanel1);
            app.computeSparsityCheckbox = uicheckbox(app.GridLayout2);
            app.computeSparsityCheckbox.Tag = 'computeSparsityCheckbox';
            app.computeSparsityCheckbox.Tooltip = 'When this is checked, a precalculation will occur before optimization that computes the sparsity vector of the gradient.  This may cut down on the calculation time for each iteration if the objective function is a function of the variables directly.  It may also cause the gradient to be computed less accurately in some situations.';
            app.computeSparsityCheckbox.Text = 'Compute Sparsity';
            app.finDiffTypeCombo = uidropdown(app.GridLayout2);
            app.finDiffTypeCombo.Items = {'Pop-up Menu'};
            app.finDiffTypeCombo.Tag = 'finDiffTypeCombo';
            app.finDiffTypeCombo.Tooltip = 'The finite difference formula to use.  Central is generally more accurate but requires twice as many function evaluations.';
            app.finDiffTypeCombo.Value = 'Pop-up Menu';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.text3 = uilabel(app.GridLayout3);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Norm. Perturbation Step';
            app.pertStepHText = uieditfield(app.GridLayout3, 'text');
            app.pertStepHText.ValueChangedFcn = createCallbackFcn(app, @pertStepHText_Callback, true);
            app.pertStepHText.Tag = 'pertStepHText';
            app.pertStepHText.Tooltip = 'The size of the perturbation step used to compute the derivative.  Good values are generally around 1E-6 or 1E-7.  Range of values: 0 < h < 1';
            app.pertStepHText.Value = '0.0';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.text4 = uilabel(app.GridLayout4);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Number of Points';
            app.numPointsText = uieditfield(app.GridLayout4, 'text');
            app.numPointsText.ValueChangedFcn = createCallbackFcn(app, @numPointsText_Callback, true);
            app.numPointsText.Tag = 'numPointsText';
            app.numPointsText.Tooltip = 'Number of points in the finite difference formula.  Generally not required to exceed 3 or so.  The higher this number, the more function evaluations that are required.  Must be an integer.';
            app.numPointsText.Value = '0.0';
            % Show the figure after all components are created
            app.lvd_finiteDiffOptionsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_finiteDiffOptionsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_finiteDiffOptionsGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_finiteDiffOptionsGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_finiteDiffOptionsGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_finiteDiffOptionsGUI)
        end
    end
end