classdef lvd_EditPluginVariables_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditPluginVariablesUIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        Panel_2                      matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        GridLayout7                  matlab.ui.container.GridLayout
        GridLayout8                  matlab.ui.container.GridLayout
        RemoveVariableButton         matlab.ui.control.Button
        AddVariableButton            matlab.ui.control.Button
        pluginVarListbox             matlab.ui.control.ListBox
        VariablePropertiesPanel      matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        GridLayout6                  matlab.ui.container.GridLayout
        VariableNameText             matlab.ui.control.EditField
        VariableNameLabel            matlab.ui.control.Label
        GridLayout5                  matlab.ui.container.GridLayout
        ValueLabel                   matlab.ui.control.Label
        ubText                       matlab.ui.control.EditField
        lbText                       matlab.ui.control.EditField
        optCheckbox                  matlab.ui.control.CheckBox
        unitLabel                    matlab.ui.control.Label
        valueText                    matlab.ui.control.EditField
        GridLayout2                  matlab.ui.container.GridLayout
        saveAndCloseButton           matlab.ui.control.Button
        text2                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        lvdData LvdData % Description
    end
    
    methods (Access = private) 
        function populateGUI(app)
            app.setPluginVarListboxStr();
            app.populatePluginData();

            if(isempty(app.pluginVarListbox.ItemsData))
                app.setEnabledStatusOfUI(false);
            else
                app.setEnabledStatusOfUI(true);
            end
        end

        function setPluginVarListboxStr(app)
            [listBoxStr, pluginVars] = app.lvdData.pluginVars.getListboxStr();

            app.pluginVarListbox.Items = listBoxStr;
            app.pluginVarListbox.ItemsData = pluginVars;
        end

        function setEnabledStatusOfUI(app, status)
            app.VariableNameText.Enable = status;
            app.valueText.Enable = status;
            app.optCheckbox.Enable = status;
            app.lbText.Enable = status;
            app.ubText.Enable = status;

            if(status == false)
                app.VariableNameText.Value = '';
                app.valueText.Value = '';
                app.optCheckbox.Value = false;
                app.lbText.Value = '';
                app.ubText.Value = '';
            end

            app.setBndEnableStatus();
            app.setRemoveButtonEnableStatus();
        end

        function setBndEnableStatus(app)
            if(app.optCheckbox.Enable)
                if(app.optCheckbox.Value == 0)
                    app.lbText.Enable = false;
                    app.ubText.Enable = false;
                else
                    app.lbText.Enable = true;
                    app.ubText.Enable = true;
                end
            end
        end

        function setRemoveButtonEnableStatus(app)
            if(isempty(app.pluginVarListbox.ItemsData))
                app.RemoveVariableButton.Enable = false;
            else
                app.RemoveVariableButton.Enable = true;
            end
        end

        function populatePluginData(app)
            pluginVarWrap = app.pluginVarListbox.Value;

            if(not(isempty(pluginVarWrap)))
                app.VariableNameText.Value = pluginVarWrap.name;
                app.valueText.Value = fullAccNum2Str(pluginVarWrap.value);

                app.optCheckbox.Value = pluginVarWrap.isVariableActive();
                
                [lb,ub] = pluginVarWrap.getBounds();
                app.lbText.Value = fullAccNum2Str(lb);
                app.ubText.Value = fullAccNum2Str(ub);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            centerUIFigure(app.EditPluginVariablesUIFigure);
            applySelectedThemeToApp(app);

            app.lvdData = varargin{1};

            app.populateGUI();

            uiwait(app.EditPluginVariablesUIFigure);
        end

        % Button pushed function: AddVariableButton
        function AddVariableButtonPushed(app, event)
            pluginVarWrap = LvdPluginOptimVarWrapper();
            pluginOptimVar = pluginVarWrap.getNewOptVar();

            app.lvdData.pluginVars.addPluginVar(pluginVarWrap);
            app.lvdData.optimizer.vars.addVariable(pluginOptimVar);

            app.setPluginVarListboxStr();
            app.pluginVarListbox.Value = pluginVarWrap;
            app.populatePluginData();

            if(isempty(app.pluginVarListbox.ItemsData))
                app.setEnabledStatusOfUI(false);
            else
                app.setEnabledStatusOfUI(true);
            end
        end

        % Button pushed function: RemoveVariableButton
        function RemoveVariableButtonPushed(app, event)
            pluginVarWrap = app.pluginVarListbox.Value;

            inUse = app.lvdData.usesPluginVariable(pluginVarWrap);
            if(not(inUse))
                app.lvdData.pluginVars.removePluginVar(pluginVarWrap);
                app.lvdData.optimizer.vars.removeVariable(pluginVarWrap.getExistingOptVar());
    
                app.setPluginVarListboxStr();
                app.populatePluginData();
    
                if(isempty(app.pluginVarListbox.ItemsData))
                    app.setEnabledStatusOfUI(false);
                else
                    app.setEnabledStatusOfUI(true);
                end    
            else
                msg = 'Cannot delete plugin variable.  It is in use as an event action.';
                uialert(app.EditPluginVariablesUIFigure, msg, 'Cannot Delete Plugin Variable', 'Icon','warning', 'Modal',true);
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButtonPushed(app, event)
            close(app.EditPluginVariablesUIFigure);
        end

        % Value changed function: pluginVarListbox
        function pluginVarListboxValueChanged(app, event)
            app.populatePluginData();

            if(isempty(app.pluginVarListbox.ItemsData))
                app.setEnabledStatusOfUI(false);
            else
                app.setEnabledStatusOfUI(true);
            end   
        end

        % Value changed function: optCheckbox
        function optCheckboxValueChanged(app, event)
            pluginVarWrap = app.pluginVarListbox.Value;
            pluginVarWrap.setIfVariableIsActive(app.optCheckbox.Value);
            
            app.setPluginVarListboxStr();
            app.setBndEnableStatus();
        end

        % Value changing function: VariableNameText
        function VariableNameTextValueChanging(app, event)
            pluginVarWrap = app.pluginVarListbox.Value;
            pluginVarWrap.name = event.Value;

            app.setPluginVarListboxStr();
        end

        % Value changed function: valueText
        function valueTextValueChanged(app, event)
            newInput = app.valueText.Value;
            newInput = attemptStrEval(newInput);
            app.valueText.Value = newInput;

            pluginVarWrap = app.pluginVarListbox.Value;

            if(not(pluginVarWrap.isVariableActive()))
                lbVar = -Inf;
                ubVar = Inf;
            else
                [lbVar, ubVar] = pluginVarWrap.getBounds();
            end
            
            value = str2double(newInput);
            enteredStr = newInput;
            numberName = 'Value';
            lb = lbVar;
            ub = ubVar;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, {}, enteredStr);

            pluginVarWrap = app.pluginVarListbox.Value;
            if(isempty(errMsg))
                pluginVarWrap.value = value;
            else
                app.valueText.Value = fullAccNum2Str(pluginVarWrap.value);

                uialert(app.EditPluginVariablesUIFigure,errMsg,'Input Error', 'Icon','error');
            end
        end

        % Value changed function: lbText
        function lbTextValueChanged(app, event)
            newInput = app.lbText.Value;
            newInput = attemptStrEval(newInput);
            app.lbText.Value = newInput;

            pluginVarWrap = app.pluginVarListbox.Value;
            [~, ubVar] = pluginVarWrap.getBounds();

            value = str2double(newInput);
            enteredStr = newInput;
            numberName = 'Lower Bound';
            lb = -realmax;
            ub = ubVar;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, {}, enteredStr);

            pluginVarWrap = app.pluginVarListbox.Value;
            if(isempty(errMsg))
                [~, ub] = pluginVarWrap.getBounds();
                pluginVarWrap.setBounds(value, ub);
            else
                [lb, ~] = pluginVarWrap.getBounds();
                app.lbText.Value = fullAccNum2Str(lb);

                uialert(app.EditPluginVariablesUIFigure,errMsg,'Input Error', 'Icon','error');
            end
        end

        % Value changed function: ubText
        function ubTextValueChanged(app, event)
            newInput = app.ubText.Value;
            newInput = attemptStrEval(newInput);
            app.ubText.Value = newInput;

            pluginVarWrap = app.pluginVarListbox.Value;
            [lbVar, ~] = pluginVarWrap.getBounds();

            value = str2double(newInput);
            enteredStr = newInput;
            numberName = 'Upper Bound';
            lb = lbVar;
            ub = realmax;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, {}, enteredStr);
            
            if(isempty(errMsg))
                [lb, ~] = pluginVarWrap.getBounds();
                pluginVarWrap.setBounds(lb, value);
            else
                [~, ub] = pluginVarWrap.getBounds();
                app.ubText.Value = fullAccNum2Str(ub);

                uialert(app.EditPluginVariablesUIFigure,errMsg,'Input Error', 'Icon','error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditPluginVariablesUIFigure = uifigure('Visible', 'off');
            app.EditPluginVariablesUIFigure.Name = 'Edit Plugin Variables';
            app.EditPluginVariablesUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout = uigridlayout(app.EditPluginVariablesUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Plugin Variables';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButtonPushed, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'close_window(1).png';
            app.saveAndCloseButton.Text = 'Close';
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.VariablePropertiesPanel = uipanel(app.GridLayout3);
            app.VariablePropertiesPanel.Title = 'Variable Properties';
            app.GridLayout4 = uigridlayout(app.VariablePropertiesPanel);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.valueText = uieditfield(app.GridLayout5, 'text');
            app.valueText.ValueChangedFcn = createCallbackFcn(app, @valueTextValueChanged, true);
            app.valueText.Tag = 'utText';
            app.valueText.Tooltip = {'The current value of the plugin variable.'};
            app.valueText.Value = '0.0';
            app.unitLabel = uilabel(app.GridLayout5);
            app.unitLabel.Tag = 'utUnitLabel';
            app.unitLabel.Tooltip = {'Plugin variable units  are determined by the user directly.'};
            app.unitLabel.Text = '<unit>';
            app.optCheckbox = uicheckbox(app.GridLayout5);
            app.optCheckbox.ValueChangedFcn = createCallbackFcn(app, @optCheckboxValueChanged, true);
            app.optCheckbox.Tag = 'optUtCheckbox';
            app.optCheckbox.Tooltip = 'Select to optimize this quantity.';
            app.optCheckbox.Text = 'Opt?';
            app.optCheckbox.Value = true;
            app.lbText = uieditfield(app.GridLayout5, 'text');
            app.lbText.ValueChangedFcn = createCallbackFcn(app, @lbTextValueChanged, true);
            app.lbText.Tag = 'utLbText';
            app.lbText.Tooltip = 'Parameter lower bound.';
            app.lbText.Value = '0.0';
            app.ubText = uieditfield(app.GridLayout5, 'text');
            app.ubText.ValueChangedFcn = createCallbackFcn(app, @ubTextValueChanged, true);
            app.ubText.Tag = 'utUbText';
            app.ubText.Tooltip = 'Parameter upper bound.';
            app.ubText.Value = '0.0';
            app.ValueLabel = uilabel(app.GridLayout5);
            app.ValueLabel.Text = 'Value';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.VariableNameLabel = uilabel(app.GridLayout6);
            app.VariableNameLabel.Text = 'Variable Name';
            app.VariableNameText = uieditfield(app.GridLayout6, 'text');
            app.VariableNameText.ValueChangingFcn = createCallbackFcn(app, @VariableNameTextValueChanging, true);
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.pluginVarListbox = uilistbox(app.GridLayout7);
            app.pluginVarListbox.ValueChangedFcn = createCallbackFcn(app, @pluginVarListboxValueChanged, true);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.AddVariableButton = uibutton(app.GridLayout8, 'push');
            app.AddVariableButton.ButtonPushedFcn = createCallbackFcn(app, @AddVariableButtonPushed, true);
            app.AddVariableButton.Icon = 'add.png';
            app.AddVariableButton.Text = 'Add Variable';
            app.RemoveVariableButton = uibutton(app.GridLayout8, 'push');
            app.RemoveVariableButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveVariableButtonPushed, true);
            app.RemoveVariableButton.Icon = 'remove.png';
            app.RemoveVariableButton.Text = 'Remove Variable';
            % Show the figure after all components are created
            app.EditPluginVariablesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditPluginVariables_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditPluginVariablesUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditPluginVariablesUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditPluginVariablesUIFigure)
        end
    end
end