classdef lvd_builtInIntegratorOptionsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_builtInIntegratorOptionsGUI  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        uipanel1                  matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        uipanel5                  matlab.ui.container.Panel
        GridLayout5               matlab.ui.container.GridLayout
        refineText                matlab.ui.control.EditField
        text17                    matlab.ui.control.Label
        uipanel3                  matlab.ui.container.Panel
        GridLayout4               matlab.ui.container.GridLayout
        text19_2                  matlab.ui.control.Label
        maxFixedStepsText         matlab.ui.control.EditField
        text18_2                  matlab.ui.control.Label
        maxStepSizeText           matlab.ui.control.EditField
        initialStepSizeText       matlab.ui.control.EditField
        integratorOutputStepText  matlab.ui.control.EditField
        text19                    matlab.ui.control.Label
        text11                    matlab.ui.control.Label
        text9                     matlab.ui.control.Label
        text18                    matlab.ui.control.Label
        text10                    matlab.ui.control.Label
        text8                     matlab.ui.control.Label
        uipanel2                  matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        relTolText                matlab.ui.control.EditField
        absTolText                matlab.ui.control.EditField
        normControlCheckbox       matlab.ui.control.CheckBox
        text4                     matlab.ui.control.Label
        text5                     matlab.ui.control.Label
        saveAndCloseButton        matlab.ui.control.Button
        text2                     matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function handles = populateGUI(app, options, handles)

            setOptsDoubleValueStrInGUI(handles, options, 'AbsTol', 'absTolText');
            setOptsDoubleValueStrInGUI(handles, options, 'RelTol', 'relTolText');

            handles.normControlCheckbox.Value = double(options.NormControl);

            setOptsDoubleValueStrInGUI(handles, options, 'integratorStepSize', 'integratorOutputStepText');
            setOptsDoubleValueStrInGUI(handles, options, 'InitialStep', 'initialStepSizeText');
            setOptsDoubleValueStrInGUI(handles, options, 'Refine', 'refineText');
            setOptsDoubleValueStrInGUI(handles, options, 'MaxStep', 'maxStepSizeText');
            setOptsDoubleValueStrInGUI(handles, options, 'maxNumFixedSteps', 'maxFixedStepsText');
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            errMsg = validateDoubleValue(handles, errMsg, 'absTolText', 'Absolute Tolerance', 1E-12, 1E-1, false);
            errMsg = validateDoubleValue(handles, errMsg, 'relTolText', 'Relative Tolerance', 1E-12, 1E-1, false);
            errMsg = validateDoubleValue(handles, errMsg, 'integratorOutputStepText', 'Integrator Output Step', -Inf, Inf, false);
            errMsg = validateDoubleValue(handles, errMsg, 'initialStepSizeText', 'Initial Step Size', 1E-12, Inf, false);
            errMsg = validateDoubleValue(handles, errMsg, 'refineText', 'Refinement Factor', 1, Inf, true);
            errMsg = validateDoubleValue(handles, errMsg, 'maxStepSizeText', 'Maximum Step Size', 1E-12, Inf, false);
            errMsg = validateDoubleValue(handles, errMsg, 'maxFixedStepsText', 'Max Fixed Steps', 2, Inf, true);
        end


        function saveAndClose(app, handles)
            options = getappdata(handles.lvd_builtInIntegratorOptionsGUI,'options');

            setOptsDoubleValueInObject(handles, options, 'AbsTol', 'absTolText');
            setOptsDoubleValueInObject(handles, options, 'RelTol', 'relTolText');

            options.NormControl = logical(handles.normControlCheckbox.Value);

            setOptsDoubleValueInObject(handles, options, 'integratorStepSize', 'integratorOutputStepText');
            setOptsDoubleValueInObject(handles, options, 'InitialStep', 'initialStepSizeText');
            setOptsDoubleValueInObject(handles, options, 'Refine', 'refineText');
            setOptsDoubleValueInObject(handles, options, 'MaxStep', 'maxStepSizeText');
            setOptsDoubleValueInObject(handles, options, 'maxNumFixedSteps', 'maxFixedStepsText');

            app.output.output = {true};
            close(handles.lvd_builtInIntegratorOptionsGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_builtInIntegratorOptionsGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_builtInIntegratorOptionsGUI (see VARARGIN)

            % Choose default command line output for lvd_builtInIntegratorOptionsGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            optionsObj = varargin{1};
            setappdata(hObject,'options',optionsObj);
            
            app.output = varargin{2};

            handles = populateGUI(app, optionsObj, handles);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_builtInIntegratorOptionsGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_builtInIntegratorOptionsGUI);
        end

        % Value changed function: absTolText
        function absTolText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to absTolText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of absTolText as text
            %        str2double(get(hObject,'String')) returns contents of absTolText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: initialStepSizeText
        function initialStepSizeText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to initialStepSizeText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of initialStepSizeText as text
            %        str2double(get(hObject,'String')) returns contents of initialStepSizeText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: integratorOutputStepText
        function integratorOutputStepText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to integratorOutputStepText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of integratorOutputStepText as text
            %        str2double(get(hObject,'String')) returns contents of integratorOutputStepText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: maxStepSizeText
        function maxStepSizeText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to maxStepSizeText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of maxStepSizeText as text
            %        str2double(get(hObject,'String')) returns contents of maxStepSizeText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: refineText
        function refineText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to refineText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of refineText as text
            %        str2double(get(hObject,'String')) returns contents of refineText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: relTolText
        function relTolText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to relTolText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of relTolText as text
            %        str2double(get(hObject,'String')) returns contents of relTolText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
%                 uiresume(handles.lvd_builtInIntegratorOptionsGUI);
                app.saveAndClose(handles);
            else
%                 msgbox(errMsg,'Invalid Integrator Options Inputs','error');
                uialert(app.lvd_builtInIntegratorOptionsGUI, errMsg, 'Invalid Integrator Options Inputs', "Icon", "error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_builtInIntegratorOptionsGUI = uifigure('Visible', 'off');
            app.lvd_builtInIntegratorOptionsGUI.Name = 'Edit Integrator Options';
            app.lvd_builtInIntegratorOptionsGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_builtInIntegratorOptionsGUI.Tag = 'lvd_builtInIntegratorOptionsGUI';
            app.GridLayout = uigridlayout(app.lvd_builtInIntegratorOptionsGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Integrator Options';
            app.saveAndCloseButton = uibutton(app.GridLayout, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout2 = uigridlayout(app.uipanel1);
            app.uipanel2 = uipanel(app.GridLayout2);
            app.uipanel2.Title = 'Tolerances';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout3 = uigridlayout(app.uipanel2);
            app.text5 = uilabel(app.GridLayout3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Absolute Tolerance';
            app.text4 = uilabel(app.GridLayout3);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Relative Tolerance';
            app.normControlCheckbox = uicheckbox(app.GridLayout3);
            app.normControlCheckbox.Tag = 'normControlCheckbox';
            app.normControlCheckbox.Tooltip = 'When NormControl is on, the solvers control the error e at each step using the norm of the solution rather than its absolute value.';
            app.normControlCheckbox.Text = 'Use Norm Control?';
            app.absTolText = uieditfield(app.GridLayout3, 'text');
            app.absTolText.ValueChangedFcn = createCallbackFcn(app, @absTolText_Callback, true);
            app.absTolText.Tag = 'absTolText';
            app.absTolText.Tooltip = 'This tolerance is a threshold below which the value of the solution becomes unimportant.';
            app.absTolText.Value = '0.0';
            app.relTolText = uieditfield(app.GridLayout3, 'text');
            app.relTolText.ValueChangedFcn = createCallbackFcn(app, @relTolText_Callback, true);
            app.relTolText.Tag = 'relTolText';
            app.relTolText.Tooltip = 'This tolerance measures the error relative to the magnitude of each solution component. Roughly speaking, it controls the number of correct digits in all solution components, except those smaller than the absolute tolerance AbsTol.';
            app.relTolText.Value = '0.0';
            app.uipanel3 = uipanel(app.GridLayout2);
            app.uipanel3.Title = 'Step Size';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout4 = uigridlayout(app.uipanel3);
            app.text8 = uilabel(app.GridLayout4);
            app.text8.Tag = 'text8';
            app.text8.Tooltip = 'Set to a positive number to output data from the integrator (for plotting and Graphical Analysis) in fixed step sizes.  Set to 0 or negative to use native integrator output.';
            app.text8.Text = 'Integrator Output Step';
            app.text10 = uilabel(app.GridLayout4);
            app.text10.Tag = 'text10';
            app.text10.Tooltip = 'An upper bound on the intial step size the integrator will use.  Tune this parameter to integrate this event faster.';
            app.text10.Text = 'Initial Step Size';
            app.text18 = uilabel(app.GridLayout4);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Maximum Step Size';
            app.text9 = uilabel(app.GridLayout4);
            app.text9.Tag = 'text9';
            app.text9.Tooltip = 'Set to a positive number to output data from the integrator (for plotting and Graphical Analysis) in fixed step sizes.  Set to 0 or negative to use native integrator output.';
            app.text9.Text = 'sec';
            app.text11 = uilabel(app.GridLayout4);
            app.text11.Tag = 'text11';
            app.text11.Tooltip = 'An upper bound on the intial step size the integrator will use.  Tune this parameter to integrate this event faster.';
            app.text11.Text = 'sec';
            app.text19 = uilabel(app.GridLayout4);
            app.text19.Tag = 'text19';
            app.text19.Tooltip = 'An upper bound on the intial step size the integrator will use.  Tune this parameter to integrate this event faster.';
            app.text19.Text = 'sec';
            app.integratorOutputStepText = uieditfield(app.GridLayout4, 'text');
            app.integratorOutputStepText.ValueChangedFcn = createCallbackFcn(app, @integratorOutputStepText_Callback, true);
            app.integratorOutputStepText.Tag = 'integratorOutputStepText';
            app.integratorOutputStepText.Tooltip = 'Set to a positive number to output data from the integrator (for plotting and Graphical Analysis) in fixed step sizes.  Set to 0 or negative to use native integrator output.';
            app.integratorOutputStepText.Value = '0.0';
            app.initialStepSizeText = uieditfield(app.GridLayout4, 'text');
            app.initialStepSizeText.ValueChangedFcn = createCallbackFcn(app, @initialStepSizeText_Callback, true);
            app.initialStepSizeText.Tag = 'initialStepSizeText';
            app.initialStepSizeText.Tooltip = 'An upper bound on the intial step size the integrator will use.  Tune this parameter to integrate this event faster.';
            app.initialStepSizeText.Value = '0.0';
            app.maxStepSizeText = uieditfield(app.GridLayout4, 'text');
            app.maxStepSizeText.ValueChangedFcn = createCallbackFcn(app, @maxStepSizeText_Callback, true);
            app.maxStepSizeText.Tag = 'maxStepSizeText';
            app.maxStepSizeText.Tooltip = 'MaxStep sets an upper bound on the size of any step taken by the solver.';
            app.maxStepSizeText.Value = '0.0';
            app.text18_2 = uilabel(app.GridLayout4);
            app.text18_2.Tag = 'text18';
            app.text18_2.Text = 'Max. Fixed Steps';
            app.maxFixedStepsText = uieditfield(app.GridLayout4, 'text');
            app.maxFixedStepsText.Tag = 'maxFixedStepsText';
            app.maxFixedStepsText.Tooltip = {'The maximum number of steps the integrator will take/output when the step size is greater than 0.'};
            app.maxFixedStepsText.Value = '0.0';
            app.text19_2 = uilabel(app.GridLayout4);
            app.text19_2.Tag = 'text19';
            app.text19_2.Tooltip = 'An upper bound on the intial step size the integrator will use.  Tune this parameter to integrate this event faster.';
            app.text19_2.Text = '';
            app.uipanel5 = uipanel(app.GridLayout2);
            app.uipanel5.Title = 'Refinement';
            app.uipanel5.Tag = 'uipanel5';
            app.GridLayout5 = uigridlayout(app.uipanel5);
            app.text17 = uilabel(app.GridLayout5);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Refinement Factor';
            app.refineText = uieditfield(app.GridLayout5, 'text');
            app.refineText.ValueChangedFcn = createCallbackFcn(app, @refineText_Callback, true);
            app.refineText.Tag = 'refineText';
            app.refineText.Tooltip = 'Specifies a factor by which the number of output points should increase in each step.  If the refinement factor is 1, then the solver returns solutions only at the end of each step.  If the refinement factor is n>1, then the solver subdivides each step into n smaller intervals and returns solutions at each point.''';
            app.refineText.Value = '0.0';
            % Show the figure after all components are created
            app.lvd_builtInIntegratorOptionsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_builtInIntegratorOptionsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_builtInIntegratorOptionsGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_builtInIntegratorOptionsGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_builtInIntegratorOptionsGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_builtInIntegratorOptionsGUI)
        end
    end
end