classdef lvd_fixedStepSizeIntegratorOptionsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_builtInIntegratorOptionsGUI  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        uipanel1                  matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        uipanel3                  matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        text19                    matlab.ui.control.Label
        maxFixedStepsText         matlab.ui.control.EditField
        text18                    matlab.ui.control.Label
        text8                     matlab.ui.control.Label
        integratorOutputStepText  matlab.ui.control.EditField
        text9                     matlab.ui.control.Label
        saveAndCloseButton        matlab.ui.control.Button
        text2                     matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function handles = populateGUI(app, options, handles)
            setOptsDoubleValueStrInGUI(handles, options, 'integratorStepSize', 'integratorOutputStepText');
            setOptsDoubleValueStrInGUI(handles, options, 'maxNumFixedSteps', 'maxFixedStepsText');
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            errMsg = validateDoubleValue(handles, errMsg, 'integratorOutputStepText', 'Integrator Output Step', 1E-6, Inf, false);
            errMsg = validateDoubleValue(handles, errMsg, 'maxFixedStepsText', 'Max Fixed Steps', 2, Inf, true);
        end


        function saveAndClose(app, handles)
            options = getappdata(handles.lvd_builtInIntegratorOptionsGUI,'options');

            setOptsDoubleValueInObject(handles, options, 'integratorStepSize', 'integratorOutputStepText');
            setOptsDoubleValueInObject(handles, options, 'maxNumFixedSteps', 'maxFixedStepsText');

            app.output.output = {true};
            close(handles.lvd_builtInIntegratorOptionsGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_fixedStepSizeIntegratorOptionsGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_fixedStepSizeIntegratorOptionsGUI (see VARARGIN)

            % Choose default command line output for lvd_fixedStepSizeIntegratorOptionsGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            optionsObj = varargin{1};
            setappdata(hObject,'options',optionsObj);

            app.output = varargin{2};

            handles = populateGUI(app, optionsObj, handles);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_fixedStepSizeIntegratorOptionsGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_builtInIntegratorOptionsGUI);
        end

        % Value changed function: integratorOutputStepText
        function integratorOutputStepText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to integratorOutputStepText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of integratorOutputStepText as text
            %        str2double(get(hObject,'String')) returns contents of integratorOutputStepText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                %                     uiresume(handles.lvd_builtInIntegratorOptionsGUI);
                app.saveAndClose(handles);
            else
                %                     msgbox(errMsg,'Invalid Integrator Options Inputs','error');
                uialert(app.lvd_builtInIntegratorOptionsGUI,errMsg,'Invalid Integrator Options Inputs',"Icon","error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_builtInIntegratorOptionsGUI = uifigure('Visible', 'off');
            app.lvd_builtInIntegratorOptionsGUI.Name = 'Edit Integrator Options';
            app.lvd_builtInIntegratorOptionsGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_builtInIntegratorOptionsGUI.Tag = 'lvd_builtInIntegratorOptionsGUI';
            app.GridLayout = uigridlayout(app.lvd_builtInIntegratorOptionsGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Integrator Options';
            app.saveAndCloseButton = uibutton(app.GridLayout, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout2 = uigridlayout(app.uipanel1);
            app.uipanel3 = uipanel(app.GridLayout2);
            app.uipanel3.Title = 'Step Size';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout3 = uigridlayout(app.uipanel3);
            app.text9 = uilabel(app.GridLayout3);
            app.text9.Tag = 'text9';
            app.text9.Tooltip = 'Set to a positive number to output data from the integrator (for plotting and Graphical Analysis) in fixed step sizes.  Set to 0 or negative to use native integrator output.';
            app.text9.Text = 'sec';
            app.integratorOutputStepText = uieditfield(app.GridLayout3, 'text');
            app.integratorOutputStepText.ValueChangedFcn = createCallbackFcn(app, @integratorOutputStepText_Callback, true);
            app.integratorOutputStepText.Tag = 'integratorOutputStepText';
            app.integratorOutputStepText.Tooltip = 'The fixed time steps at which the integrator will propagate.  Higher steps will run faster at the expense of accuracy.  Smaller step sizes will be slower but more accurate.';
            app.integratorOutputStepText.Value = '0.0';
            app.text8 = uilabel(app.GridLayout3);
            app.text8.Tag = 'text8';
            app.text8.Tooltip = 'The fixed time steps at which the integrator will propagate.  Higher steps will run faster at the expense of accuracy.  Smaller step sizes will be slower but more accurate.';
            app.text8.Text = 'Integrator Step Size';
            app.text18 = uilabel(app.GridLayout3);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Max. Fixed Steps';
            app.maxFixedStepsText = uieditfield(app.GridLayout3, 'text');
            app.maxFixedStepsText.Tag = 'maxFixedStepsText';
            app.maxFixedStepsText.Tooltip = {'The maximum number of steps the integrator will take/output when the step size is greater than 0.'};
            app.maxFixedStepsText.Value = '0.0';
            app.text19 = uilabel(app.GridLayout3);
            app.text19.Tag = 'text19';
            app.text19.Tooltip = 'An upper bound on the intial step size the integrator will use.  Tune this parameter to integrate this event faster.';
            app.text19.Text = '';
            % Show the figure after all components are created
            app.lvd_builtInIntegratorOptionsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_fixedStepSizeIntegratorOptionsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_builtInIntegratorOptionsGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_fixedStepSizeIntegratorOptionsGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_builtInIntegratorOptionsGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_builtInIntegratorOptionsGUI)
        end
    end
end