classdef referenceFrameSelectComp < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout             matlab.ui.container.GridLayout
        SetFrameOptionsButton  matlab.ui.control.Button
        GridLayout2            matlab.ui.container.GridLayout
        FrameDropDown          matlab.ui.control.DropDown
        FrameLabel             matlab.ui.control.Label
        FrameNameLabel         matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        FrameChanged % Something with the underlying frame changed.
    end

    properties (Access = private)
        bodyFixedFrame BodyFixedFrame
        inertialFrame BodyCenteredInertialFrame
        twoBodyRotFrame TwoBodyRotatingFrame
        userDefinedGeomFrame UserDefinedGeometricFrame
    end
    
    properties (Access = public)
        Enabled (1,1) logical = true;
    end
    
    methods (Access = public)
        function frame = getSelectedFrame(comp)
            frameEnum = comp.FrameDropDown.Value;
            switch frameEnum
                case ReferenceFrameEnum.BodyCenteredInertial
                    frame = comp.inertialFrame;

                case ReferenceFrameEnum.BodyFixedRotating
                    frame = comp.bodyFixedFrame;

                case ReferenceFrameEnum.TwoBodyRotating
                    frame = comp.twoBodyRotFrame;

                case ReferenceFrameEnum.UserDefined
                    frame = comp.userDefinedGeomFrame;

                otherwise
                    error('Unknown reference frame type: %s', frameEnum.name);
            end
        end

        function initializeWithFrames(comp, lvdData)
            %Must be called before using!
            arguments
                comp(1,1) referenceFrameSelectComp
                lvdData(1,1) LvdData    
            end

            celBodyData = lvdData.celBodyData;
            bodyInfo = lvdData.getDefaultInitialBodyInfo(celBodyData);
            
            comp.inertialFrame = bodyInfo.getBodyCenteredInertialFrame();
            comp.bodyFixedFrame = bodyInfo.getBodyFixedFrame();
            
            parentBodyInfo = bodyInfo.getParBodyInfo();
            childBodyInfos = bodyInfo.getChildrenBodyInfo();

            if(not(isempty(childBodyInfos)))
                comp.twoBodyRotFrame = TwoBodyRotatingFrame(bodyInfo, childBodyInfos(1), TwoBodyRotatingFrameOriginEnum.Primary, celBodyData);

            elseif(not(isempty(parentBodyInfo)))
                comp.twoBodyRotFrame = TwoBodyRotatingFrame(parentBodyInfo, bodyInfo, TwoBodyRotatingFrameOriginEnum.Secondary, celBodyData);

            else
                bodyInfosWithChildren = celBodyData.getAllBodiesWithChildren();
                if(not(isempty(bodyInfosWithChildren)))
                    bodyInfoWithChildren1 = bodyInfosWithChildren(1);
                    childBodyInfos = bodyInfoWithChildren1.getChildrenBodyInfo();

                    comp.twoBodyRotFrame = TwoBodyRotatingFrame(bodyInfoWithChildren1, childBodyInfos(1), TwoBodyRotatingFrameOriginEnum.Primary, celBodyData);
                end
            end
            
            geomRefFramesSet = lvdData.geometry.refFrames;
            geometricFrame = AbstractGeometricRefFrame.empty(1,0);
            for(i=1:geomRefFramesSet.getNumRefFrames())
                frame = geomRefFramesSet.getRefFrameAtInd(i);
                if(frame.isVehDependent() == false)
                    geometricFrame = frame;
                    break;
                end
            end
            if(not(isempty(geometricFrame)))
                comp.userDefinedGeomFrame = UserDefinedGeometricFrame(geometricFrame, lvdData);
            end

        end

        function setSelectedFrame(comp, frame)
            arguments
                comp(1,1) referenceFrameSelectComp
                frame(1,1) AbstractReferenceFrame
            end

            frameEnum = frame.typeEnum;
            switch frameEnum
                case ReferenceFrameEnum.BodyCenteredInertial
                    comp.inertialFrame = frame;

                case ReferenceFrameEnum.BodyFixedRotating
                    comp.bodyFixedFrame = frame;

                case ReferenceFrameEnum.TwoBodyRotating
                    comp.twoBodyRotFrame = frame;

                case ReferenceFrameEnum.UserDefined
                    comp.userDefinedGeomFrame = frame;

                otherwise
                    error('Unknown reference frame type: %s', frameEnum.name);
            end

            comp.FrameDropDown.Value = frame.typeEnum;
            comp.FrameNameLabel.Text = frame.getNameStr();
        end

        function themeKsptotComp(comp, appThemer, theme)
            arguments
                comp(1,1) referenceFrameSelectComp
                appThemer(1,1) AppThemer
                theme(1,1) AppColorTheme
            end

            comp.BackgroundColor = theme.bgColor;
            appThemer.themeWidget(comp.FrameNameLabel, theme);
            appThemer.themeWidget(comp.FrameLabel, theme);
            appThemer.themeWidget(comp.FrameDropDown, theme);
            appThemer.themeWidget(comp.SetFrameOptionsButton, theme);
            appThemer.themeWidget(comp.GridLayout, theme);
            appThemer.themeWidget(comp.GridLayout2, theme);
        end
    end
    
    methods (Access = private)
        function populateGUI(comp)
            [listBoxStr, m] = ReferenceFrameEnum.getListBoxStr();
            comp.FrameDropDown.Items = listBoxStr;
            comp.FrameDropDown.ItemsData = m;
            
            frame = comp.getSelectedFrame();
            if(not(isempty(frame)))
                comp.FrameDropDown.Value = frame.typeEnum;
                comp.FrameNameLabel.Text = frame.getNameStr();
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.populateGUI();
        end

        % Button pushed function: SetFrameOptionsButton
        function SetFrameOptionsButtonPushed(comp, event)
            frame = comp.getSelectedFrame();
            newFrame = frame.editFrameDialogUI();
            comp.setSelectedFrame(newFrame);

            frameEnum = newFrame.typeEnum;
            switch frameEnum
                case ReferenceFrameEnum.BodyCenteredInertial
                    comp.inertialFrame = newFrame;

                case ReferenceFrameEnum.BodyFixedRotating
                    comp.bodyFixedFrame = newFrame;

                case ReferenceFrameEnum.TwoBodyRotating
                    comp.twoBodyRotFrame = newFrame;

                case ReferenceFrameEnum.UserDefined
                    comp.userDefinedGeomFrame = newFrame;

                otherwise
                    error('Unknown reference frame type: %s', frameEnum.name);
            end

            comp.FrameNameLabel.Text = newFrame.getNameStr();
            notify(comp, 'FrameChanged');
        end

        % Value changed function: FrameDropDown
        function FrameDropDownValueChanged(comp, event)
            frame = comp.getSelectedFrame();
            comp.FrameNameLabel.Text = frame.getNameStr();

            notify(comp, 'FrameChanged');
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            if(comp.Enabled)
                comp.SetFrameOptionsButton.Enable = "on";
                comp.FrameDropDown.Enable = "on";
                comp.FrameLabel.Enable = "on";
                comp.FrameNameLabel.Enable = "on";
            else
                comp.SetFrameOptionsButton.Enable = "off";
                comp.FrameDropDown.Enable = "off";
                comp.FrameLabel.Enable = "off";
                comp.FrameNameLabel.Enable = "off";
            end
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 264 74];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.HandleVisibility = 'on';
            comp.GridLayout = uigridlayout(comp);
            comp.FrameNameLabel = uilabel(comp.GridLayout);
            comp.FrameNameLabel.Text = 'FrameName';
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.FrameLabel = uilabel(comp.GridLayout2);
            comp.FrameLabel.Text = 'Frame';
            comp.FrameDropDown = uidropdown(comp.GridLayout2);
            comp.FrameDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FrameDropDownValueChanged, true);
            comp.SetFrameOptionsButton = uibutton(comp.GridLayout, 'push');
            comp.SetFrameOptionsButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetFrameOptionsButtonPushed, true);
            comp.SetFrameOptionsButton.Icon = 'reference_frame.png';
            comp.SetFrameOptionsButton.Text = 'Set Frame Options';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end