classdef lvd_SensorReports < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SensorReportFigureGUI    matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout2              matlab.ui.container.GridLayout
        TabGroup                 matlab.ui.container.TabGroup
        GenerateReportTab        matlab.ui.container.Tab
        GridLayout4              matlab.ui.container.GridLayout
        GridLayout8              matlab.ui.container.GridLayout
        ReportFolderSelector     wt.FileSelector
        ReportOutputFolderLabel  matlab.ui.control.Label
        TargetsPanel             matlab.ui.container.Panel
        GridLayout6              matlab.ui.container.GridLayout
        TargetsListbox           matlab.ui.control.ListBox
        SensorsPanel             matlab.ui.container.Panel
        GridLayout5              matlab.ui.container.GridLayout
        SensorsListbox           matlab.ui.control.ListBox
        GenerateReportButton     matlab.ui.control.Button
        DataViewerTab            matlab.ui.container.Tab
        GridLayout9              matlab.ui.container.GridLayout
        DataPanel                matlab.ui.container.Panel
        GridLayout11             matlab.ui.container.GridLayout
        dataAxes                 matlab.ui.control.UIAxes
        GridLayout10             matlab.ui.container.GridLayout
        PlotDataButton           matlab.ui.control.Button
        DataSetListbox           matlab.ui.control.ListBox
        DataTypeCombo            matlab.ui.control.DropDown
        TargetDataCombo          matlab.ui.control.DropDown
        SensorDataCombo          matlab.ui.control.DropDown
        DataSetLabel             matlab.ui.control.Label
        DataTypeLabel            matlab.ui.control.Label
        TargetLabel              matlab.ui.control.Label
        SensorLabel              matlab.ui.control.Label
        OutputInstructionsTab    matlab.ui.container.Tab
        GridLayout7              matlab.ui.container.GridLayout
        Label2                   matlab.ui.control.Label
        text2                    matlab.ui.control.Label
        Panel                    matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        BusyStatusLabel          matlab.ui.control.Label
        ProgressBar              wt.ProgressBar
    end


    properties (Access = private)
        lvdData LvdData
        usedSensors AbstractSensor
        usedTargets AbstractSensorTarget
        
        coverageData
        azData
        elData
        rangeData
        angleToBoresightData 
    end
    methods (Access = private)
        function populateGUI(app)
            [listboxStr, sensors] = app.lvdData.sensors.getListboxStr();
            app.SensorsListbox.Value = {};
            app.SensorsListbox.Items = listboxStr;
            app.SensorsListbox.ItemsData = sensors;
            if(~isempty(sensors))
                app.SensorsListbox.Value = sensors;
            end

            [listboxStr, targets] = app.lvdData.sensorTgts.getListboxStr();
            app.TargetsListbox.Value = {};
            app.TargetsListbox.Items = listboxStr;
            app.TargetsListbox.ItemsData = targets;
            if(~isempty(targets))
                app.TargetsListbox.Value = targets;
            end
            
            app.ProgressBar.startProgress('');
            app.BusyStatusLabel.Text = '';
        end
        
        function populateDataTab(app)
            if(numel(app.usedSensors))
                for(i=1:length(app.usedSensors)) 
                    str{i} = app.usedSensors(i).getListboxStr(); %#ok<AGROW> 
                end
                app.SensorDataCombo.Items = str;
                app.SensorDataCombo.ItemsData = app.usedSensors;
                
                for(i=1:length(app.usedTargets))
                    str{i} = app.usedTargets(i).getListboxStr();
                end
                app.TargetDataCombo.Items = str;
                app.TargetDataCombo.ItemsData = app.usedTargets;
                
                [listboxStr, m] = SensorReportDataTypesEnum.getListBoxStr();
                app.DataTypeCombo.Items = listboxStr;
                app.DataTypeCombo.ItemsData = m;
                
                app.populateDataSetListbox();
            end
        end
        
        function populateDataSetListbox(app)
            row = app.SensorDataCombo.Value == app.SensorDataCombo.ItemsData;
            col = app.TargetDataCombo.Value == app.TargetDataCombo.ItemsData;
            
            dataTs = app.getSelectedDataTables();
            T = dataTs{row,col};
            varNames = T.Properties.VariableNames;
            varNames = varNames(2:end); %get rid of time
            app.DataSetListbox.Items = varNames;
        end
        
        function dataTs = getSelectedDataTables(app)
            m = app.DataTypeCombo.Value;
            switch m
                case SensorReportDataTypesEnum.Coverage
                    dataTs = app.coverageData;
                    
                case SensorReportDataTypesEnum.AzAngle
                    dataTs = app.azData;
                    
                case SensorReportDataTypesEnum.ElAngle
                    dataTs = app.elData;
                    
                case SensorReportDataTypesEnum.Range
                    dataTs = app.rangeData;
                    
                case SensorReportDataTypesEnum.AngleBoresight
                    dataTs = app.angleToBoresightData;
                    
                otherwise 
                    error('Unknown report data type: %s', m.name);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            centerUIFigure(app.SensorReportFigureGUI);
            applySelectedThemeToApp(app);

            app.lvdData = varargin{1};

            app.populateGUI();

            uiwait(app.SensorReportFigureGUI);
        end

        % Button pushed function: GenerateReportButton
        function GenerateReportButtonPushed(app, event)
            sensors = app.SensorsListbox.Value;
            targets = app.TargetsListbox.Value;
            
            if(numel(sensors) >= 1 && numel(targets) >= 1)
                app.GenerateReportButton.Enable = false;
                app.PlotDataButton.Enable = false;
                
                if(app.ReportFolderSelector.ValueIsValidPath)
                    reportFolder = app.ReportFolderSelector.FullPath; 
                else
                    reportFolder = pwd;
                end
                
                app.usedSensors = AbstractSensor.empty(1,0);
                app.usedTargets = targets;
                
                numRowsData = numel(sensors);
                numColsData = numel(targets);
                
                app.coverageData = cell(numRowsData, numColsData);
                app.azData = cell(numRowsData, numColsData);
                app.elData = cell(numRowsData, numColsData);
                app.rangeData = cell(numRowsData, numColsData);
                app.angleToBoresightData = cell(numRowsData, numColsData);
                
                try
                    for(i=1:length(sensors)) %#ok<*NO4LP> 
                        sensor = sensors(i);
                        
                        report = LaunchVehicleSensorReport(sensor, app.lvdData);
                        [filepath(i), coverageT, azT, elT, rngT, angleToBoresightT] = report.generateReport(targets, app.ProgressBar, app.BusyStatusLabel, i, length(sensors), reportFolder); %#ok<AGROW> 
                        
                        app.usedSensors(i) = sensor;
                        app.coverageData(i,:) = coverageT;
                        app.azData(i,:) = azT;
                        app.elData(i,:) = elT;
                        app.rangeData(i,:) = rngT;
                        app.angleToBoresightData(i,:) = angleToBoresightT;
                    end
                catch ME
                    app.ProgressBar.finishProgress('');
                    app.BusyStatusLabel.Text = 'Error.';
                    app.GenerateReportButton.Enable = true;
                    app.PlotDataButton.Enable = false;
                    uialert(app.SensorReportFigureGUI, ME.message, 'Report Generation Failed', "Icon","error");
                    return;
                end
                
                app.ProgressBar.finishProgress('');
                app.BusyStatusLabel.Text = 'Done.';
                app.GenerateReportButton.Enable = true;
                
                app.PlotDataButton.Enable = true;
                
                app.populateDataTab();
                
                msg = sprintf("The following XLS files have been generated: \n\n%s", sprintf("%s\n",filepath));
                uialert(app.SensorReportFigureGUI, msg, 'Reports Generated', "Icon","success");
            else
                uialert(app.SensorReportFigureGUI, 'There must be at least one sensor and target selected.', 'Selection Error', "Icon","error");
            end
        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
            row = app.SensorDataCombo.Value == app.SensorDataCombo.ItemsData;
            col = app.TargetDataCombo.Value == app.TargetDataCombo.ItemsData;
            
            m = app.DataTypeCombo.Value;
            dataTs = app.getSelectedDataTables();
                        
            T = dataTs{row,col};
            dataCols = string(app.DataSetListbox.Value);
            
            indvar = T(:,1);
            data = T(:,dataCols);
            if(width(data) > 0)
                cla(app.dataAxes, "reset");
                
                hold(app.dataAxes,'on');
                for(i=1:width(data))
                    dataCol = data(:,i);
                    hL(i) = plot(app.dataAxes, table2array(indvar), table2array(dataCol), '-o'); %#ok<AGROW> 
                    legendStr(i) = dataCol.Properties.VariableNames; %#ok<AGROW> 
                end
                
                xlabel(app.dataAxes, 'Universal Time [s]');
                ylabel(app.dataAxes, sprintf('%s [%s]', m.name, m.unitStr));
                legend(app.dataAxes, hL, legendStr, "Location","best");
                grid(app.dataAxes, 'minor');
                hold(app.dataAxes,'off');
            end

            applySelectedThemeToApp(app);
        end

        % Value changed function: TargetDataCombo
        function TargetDataComboValueChanged(app, event)
            app.populateDataTab();
        end

        % Value changed function: DataTypeCombo
        function DataTypeComboValueChanged(app, event)
            app.populateDataTab();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SensorReportFigureGUI = uifigure('Visible', 'off');
            app.SensorReportFigureGUI.Name = 'Sensor Reports';
            app.SensorReportFigureGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout = uigridlayout(app.SensorReportFigureGUI);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.ProgressBar = wt.ProgressBar(app.GridLayout3);
            app.ProgressBar.ShowTimeRemaining = 'off';
            app.BusyStatusLabel = uilabel(app.GridLayout3);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.text2 = uilabel(app.GridLayout2);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Sensor Reports';
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.GenerateReportTab = uitab(app.TabGroup);
            app.GenerateReportTab.Title = 'Generate Report';
            app.GridLayout4 = uigridlayout(app.GenerateReportTab);
            app.GenerateReportButton = uibutton(app.GridLayout4, 'push');
            app.GenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButtonPushed, true);
            app.GenerateReportButton.Icon = 'report.png';
            app.GenerateReportButton.Text = 'Generate Report';
            app.SensorsPanel = uipanel(app.GridLayout4);
            app.SensorsPanel.Title = 'Sensors';
            app.GridLayout5 = uigridlayout(app.SensorsPanel);
            app.SensorsListbox = uilistbox(app.GridLayout5);
            app.SensorsListbox.Multiselect = 'on';
            app.SensorsListbox.Value = {'Item 1'};
            app.TargetsPanel = uipanel(app.GridLayout4);
            app.TargetsPanel.Title = 'Targets';
            app.GridLayout6 = uigridlayout(app.TargetsPanel);
            app.TargetsListbox = uilistbox(app.GridLayout6);
            app.TargetsListbox.Multiselect = 'on';
            app.TargetsListbox.Value = {'Item 1'};
            app.GridLayout8 = uigridlayout(app.GridLayout4);
            app.ReportOutputFolderLabel = uilabel(app.GridLayout8);
            app.ReportOutputFolderLabel.Text = 'Report Output Folder:';
            app.ReportFolderSelector = wt.FileSelector(app.GridLayout8);
            app.ReportFolderSelector.SelectionType = 'folder';
            app.ReportFolderSelector.Tooltip = "The folder in which the generated report XLS files wil be placed.  If empty, it will be the current working MATLAB directory. ";
            app.DataViewerTab = uitab(app.TabGroup);
            app.DataViewerTab.Title = 'Data Viewer';
            app.GridLayout9 = uigridlayout(app.DataViewerTab);
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.SensorLabel = uilabel(app.GridLayout10);
            app.SensorLabel.Text = 'Sensor';
            app.TargetLabel = uilabel(app.GridLayout10);
            app.TargetLabel.Text = 'Target';
            app.DataTypeLabel = uilabel(app.GridLayout10);
            app.DataTypeLabel.Text = 'Data Type';
            app.DataSetLabel = uilabel(app.GridLayout10);
            app.DataSetLabel.Text = 'Data Set';
            app.SensorDataCombo = uidropdown(app.GridLayout10);
            app.SensorDataCombo.Items = {};
            app.SensorDataCombo.Value = {};
            app.TargetDataCombo = uidropdown(app.GridLayout10);
            app.TargetDataCombo.Items = {};
            app.TargetDataCombo.ValueChangedFcn = createCallbackFcn(app, @TargetDataComboValueChanged, true);
            app.TargetDataCombo.Value = {};
            app.DataTypeCombo = uidropdown(app.GridLayout10);
            app.DataTypeCombo.Items = {};
            app.DataTypeCombo.ValueChangedFcn = createCallbackFcn(app, @DataTypeComboValueChanged, true);
            app.DataTypeCombo.Value = {};
            app.DataSetListbox = uilistbox(app.GridLayout10);
            app.DataSetListbox.Items = {};
            app.DataSetListbox.Multiselect = 'on';
            app.DataSetListbox.Value = {};
            app.PlotDataButton = uibutton(app.GridLayout10, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Enable = 'off';
            app.PlotDataButton.Text = 'Plot Data';
            app.DataPanel = uipanel(app.GridLayout9);
            app.DataPanel.Title = 'Data';
            app.GridLayout11 = uigridlayout(app.DataPanel);
            app.dataAxes = uiaxes(app.GridLayout11);
            app.OutputInstructionsTab = uitab(app.TabGroup);
            app.OutputInstructionsTab.Title = 'Output Instructions';
            app.GridLayout7 = uigridlayout(app.OutputInstructionsTab);
            app.Label2 = uilabel(app.GridLayout7);
            app.Label2.Text = {'Each sensor selected produces an XLS file with data for each selected target.  The following types of worksheets are created in each sensor''s XLS file:'; ''; 'Coverage - A 0 means that the sensor cannot see that target point and a 1 means the sensor can see the target point.  Instantaneous coverage is how much of the target the sensor currently sees and cumulative coverage is how much of the target has been cumulatively seen over time.  In these cases, 0 means no coverage and 1 means full coverage.'; ''; 'Azimuth - The azimuth angle from the sensor boresight to the target point, in degrees.  Azimuth is defined as the angle in the sensor''s XY plane, counterclockwise about the sensor''s Z axis.'; ''; 'Elevation - The elevation angle from the sensor boresight to the target point, in degrees.  Elevation is defined as the angle to the target above or below the sensor''s XY plane.'; ''; 'Range - The range from the sensor to the target point, in kilometers.'; ''; 'Angle - The absolute angle from the sensor boresight to the target point, in degrees.'; ''; 'The sensor coordinate system is defined such that the X axis is pointed along the sensor boresight.  The sensor Z axis is constrained to be as close to the sensor''s pointing model''s Z axis as possible.'; ''};
            % Show the figure after all components are created
            app.SensorReportFigureGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_SensorReports(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SensorReportFigureGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SensorReportFigureGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SensorReportFigureGUI)
        end
    end
end