classdef lvd_EditRectangularSensorGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditRectangularSensorGUI      matlab.ui.Figure
        GridLayout11                  matlab.ui.container.GridLayout
        Panel_2                       matlab.ui.container.Panel
        GridLayout12                  matlab.ui.container.GridLayout
        StatusLabel                   matlab.ui.control.Label
        BusySpinIcon                  matlab.ui.control.Image
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout7                   matlab.ui.container.GridLayout
        DisplayPanel                  matlab.ui.container.Panel
        GridLayout9                   matlab.ui.container.GridLayout
        DisplayAxes                   matlab.ui.control.UIAxes
        Panel                         matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        DisplayPropertiesPanel        matlab.ui.container.Panel
        GridLayout10                  matlab.ui.container.GridLayout
        Image4                        matlab.ui.control.Image
        Image3                        matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        ShowMeshEdgesCheckbox         matlab.ui.control.CheckBox
        sensorNameLabel_5             matlab.ui.control.Label
        SensorColorCombo              matlab.ui.control.DropDown
        SensorAlphaSlider             matlab.ui.control.Slider
        sensorNameLabel_4             matlab.ui.control.Label
        sensorNameLabel_3             matlab.ui.control.Label
        uipanel2_2                    matlab.ui.container.Panel
        GridLayout4_2                 matlab.ui.container.GridLayout
        Image                         matlab.ui.control.Image
        SensorOriginCombo             matlab.ui.control.DropDown
        sensorNameLabel_2             matlab.ui.control.Label
        InitialPointingModelPanel     matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        EditPointingModelButton       matlab.ui.control.Button
        PointingModelCombo            matlab.ui.control.DropDown
        InitialSensorDefinitionPanel  matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        Image8                        matlab.ui.control.Image
        Image7                        matlab.ui.control.Image
        Image6                        matlab.ui.control.Image
        Image5                        matlab.ui.control.Image
        SensorHalfDecAngleText        matlab.ui.control.EditField
        text18_2                      matlab.ui.control.Label
        text6_3                       matlab.ui.control.Label
        SensorActiveCheckbox          matlab.ui.control.CheckBox
        text5_2                       matlab.ui.control.Label
        SensorMaxRangeText            matlab.ui.control.EditField
        text6_2                       matlab.ui.control.Label
        text6                         matlab.ui.control.Label
        SensorHalfAzAngleText         matlab.ui.control.EditField
        text18                        matlab.ui.control.Label
        text5                         matlab.ui.control.Label
        uipanel2                      matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        sensorNameText                matlab.ui.control.EditField
        sensorNameLabel               matlab.ui.control.Label
        GridLayout2                   matlab.ui.container.GridLayout
        cancelButton                  matlab.ui.control.Button
        saveAndCloseButton            matlab.ui.control.Button
        text2                         matlab.ui.control.Label
    end


    properties (Access = private)
        lvdData LvdData
        sensor RectangularSensor

        fixedInCoordSysSteeringModel FixedInCoordSysSensorSteeringModel
        fixedInVehAttSteeringModel FixedInVehicleFrameSensorSteeringModel

        output AppDesignerGUIOutput

        sensorMesh
        originMarker
        axes
    end

    methods (Access = private)
        function populateGUI(app)
            app.sensorNameText.Value = app.sensor.getName();

            app.SensorHalfAzAngleText.Value = fullAccNum2Str(rad2deg(app.sensor.azAngle));
            app.SensorHalfDecAngleText.Value = fullAccNum2Str(rad2deg(app.sensor.decAngle));
            app.SensorMaxRangeText.Value = fullAccNum2Str(app.sensor.range);
            app.SensorActiveCheckbox.Value = app.sensor.initActiveTf;

            [listBoxStr, points] = app.lvdData.geometry.points.getListboxStr();
            app.SensorOriginCombo.Items = listBoxStr;
            app.SensorOriginCombo.ItemsData = points;
            app.SensorOriginCombo.Value = app.sensor.origin;

            enum = app.sensor.steeringModel.getEnum();
            [listBoxStr, m] = SensorSteeringModelEnum.getListBoxStr();
            app.PointingModelCombo.Items = listBoxStr;
            app.PointingModelCombo.ItemsData = m;
            app.PointingModelCombo.Value = enum;

            switch enum
                case SensorSteeringModelEnum.FixedInCoordSys
                    app.fixedInCoordSysSteeringModel = app.sensor.steeringModel;
                    app.fixedInVehAttSteeringModel = FixedInVehicleFrameSensorSteeringModel(0,0,0, app.lvdData);

                case SensorSteeringModelEnum.FixedVehicleBodyFrame
                    numCoordSys = app.lvdData.geometry.coordSyses.getNumCoordSyses();
                    if(numCoordSys >= 1)
                        coordSys = app.lvdData.geometry.coordSyses.getCoordSysAtInd(1);
                        app.fixedInCoordSysSteeringModel = FixedInCoordSysSensorSteeringModel(0,0,0,coordSys, app.lvdData);
                    end

                    app.fixedInVehAttSteeringModel = app.sensor.steeringModel;

                otherwise
                    error('Unknown Sensor Pointing Model Type: %s', enum.name);
            end

            lvdSetupColorPicker(app.EditRectangularSensorGUI, app.GridLayout10, 1, 3, app.SensorColorCombo, lvd_colorSpecToRGB(app.sensor.color), 'Pick a color.', 'sensorColor', @(rgb) app.updateDisplayAxes());

            app.SensorAlphaSlider.Value = app.sensor.alpha;

            app.ShowMeshEdgesCheckbox.Value = app.sensor.showMeshEdges;

            axis(app.DisplayAxes, 'equal');
            grid(app.DisplayAxes, 'minor');
            view(app.DisplayAxes, 3);

            app.updateDisplayAxes();
        end

        function saveAndClose(app)
            app.sensor.name = app.sensorNameText.Value;

            app.sensor.azAngle = deg2rad(str2double(app.SensorHalfAzAngleText.Value));
            app.sensor.decAngle = deg2rad(str2double(app.SensorHalfDecAngleText.Value));
            app.sensor.range = str2double(app.SensorMaxRangeText.Value);
            app.sensor.initActiveTf = app.SensorActiveCheckbox.Value;

            app.sensor.origin = app.SensorOriginCombo.Value;

            app.sensor.steeringModel = app.getSelectedSteeringModel();

            app.sensor.color = lvdGetColorPickerRGB(app.EditRectangularSensorGUI, 'sensorColor', app.sensor.color);
            app.sensor.alpha = app.SensorAlphaSlider.Value;
            app.sensor.showMeshEdges = app.ShowMeshEdgesCheckbox.Value;

            app.output.output = {true};
            close(app.EditRectangularSensorGUI);
        end

        function errMsg = validateInputs(app)
            errMsg = {};

            value = str2double(app.SensorHalfAzAngleText.Value);
            enteredStr = app.SensorHalfAzAngleText.Value;
            numberName = 'Sensor Azimuth Angle';
            lb = 0.001;
            ub = 90;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(app.SensorHalfDecAngleText.Value);
            enteredStr = app.SensorHalfDecAngleText.Value;
            numberName = 'Sensor Elevation Angle';
            lb = 0.001;
            ub = 90;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(app.SensorMaxRangeText.Value);
            enteredStr = app.SensorMaxRangeText.Value;
            numberName = 'Sensor Maximum Range';
            lb = 0.001;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(strtrim(app.sensorNameText.Value)))
                errMsg{end+1} = 'Sensor name must contain more than white space and must not be empty.';
            end
        end

        function steeringModel = getSelectedSteeringModel(app)
            enum = app.PointingModelCombo.Value;
            switch enum
                case SensorSteeringModelEnum.FixedInCoordSys
                    steeringModel = app.fixedInCoordSysSteeringModel;

                case SensorSteeringModelEnum.FixedVehicleBodyFrame
                    steeringModel = app.fixedInVehAttSteeringModel;

                otherwise
                    error('Unknown Sensor Pointing Model Type: %s', enum.name);
            end
        end

        function updateDisplayAxes(app)
            errMsg = app.validateInputs();

            if(isempty(errMsg))
                app.setTaskbarIconText(true, 'Drawing Sensor');
                name = app.sensorNameText.Value;

                azAngle = deg2rad(str2double(app.SensorHalfAzAngleText.Value));
                decAngle = deg2rad(str2double(app.SensorHalfDecAngleText.Value));
                range = str2double(app.SensorMaxRangeText.Value);

                origin = app.SensorOriginCombo.Value;

                steeringModel = app.getSelectedSteeringModel();

                displaySensor = RectangularSensor(name, azAngle, decAngle, range, origin, steeringModel, app.lvdData);

                bodyInfo = LvdData.getDefaultInitialBodyInfo(app.lvdData.celBodyData);
                frame = bodyInfo.getBodyCenteredInertialFrame();
                scElem = CartesianElementSet(0, [0;0;0], [0;0;0], frame);

                dcm = eye(3);

                sensorState = displaySensor.getInitialState();
                sensorRange = sensorState.getSensorMaxRange();
                [V,F] = displaySensor.getSensorMesh(sensorState, scElem, dcm, frame);

                color = lvdGetColorPickerRGB(app.EditRectangularSensorGUI, 'sensorColor', app.sensor.color);
                alpha = app.SensorAlphaSlider.Value;
                showMeshEdges = app.ShowMeshEdgesCheckbox.Value;

                if(showMeshEdges)
                    meshLineStyle = '-';
                else
                    meshLineStyle = 'none';
                end

                newCartElem = origin.getPositionAtTime(0, scElem, frame);
                rVectOrigin = newCartElem.rVect;

                V(:,1) = V(:,1) - rVectOrigin(1);
                V(:,2) = V(:,2) - rVectOrigin(2);
                V(:,3) = V(:,3) - rVectOrigin(3);

                if(isempty(app.sensorMesh))
                    app.sensorMesh = drawMesh(app.DisplayAxes, V, F, 'FaceColor',color,'FaceAlpha',alpha, 'LineStyle',meshLineStyle, 'EdgeAlpha',alpha);
                    app.originMarker = plot3(app.DisplayAxes, 0, 0, 0, 'Marker', 'd', 'MarkerFaceColor','r', 'MarkerEdgeColor','k');

                    app.axes = hgtransform('Parent', app.DisplayAxes);
                    quiver3(app.DisplayAxes, 0,0,0, 1,0,0, 'off', 'LineWidth',3, "Color",'r', 'LineStyle',"-", "ShowArrowHead","on", 'Parent',app.axes);
                    quiver3(app.DisplayAxes, 0,0,0, 0,1,0, 'off', 'LineWidth',3, "Color",'g', 'LineStyle',"-", "ShowArrowHead","on", 'Parent',app.axes);
                    quiver3(app.DisplayAxes, 0,0,0, 0,0,1, 'off', 'LineWidth',3, "Color",'b', 'LineStyle',"-", "ShowArrowHead","on", 'Parent',app.axes);
                else
                    app.sensorMesh.Faces = F;
                    app.sensorMesh.Vertices = V;
                    app.sensorMesh.FaceColor = color;
                    app.sensorMesh.FaceAlpha = alpha;
                    app.sensorMesh.EdgeAlpha = alpha;
                    app.sensorMesh.LineStyle = meshLineStyle;

                    app.originMarker.XData = 0;
                    app.originMarker.YData = 0;
                    app.originMarker.ZData = 0;
                end

                r = vrrotmat2vec(steeringModel.getSensorParentDcmToInertial(0, scElem, dcm, frame));
                M = makehgtform('axisrotate', r(1:3), r(4), 'scale', sensorRange/3);
                app.axes.Matrix = M;

                app.setTaskbarIconText(false, '');
                drawnow;
            else
                errMsg = horzcat({'Could not update the display because there were errors in the inputs.'}, errMsg);
                uialert(app.EditRectangularSensorGUI, errMsg, 'Cannot Update Display',"Icon","warning");
            end
        end

        function setTaskbarIconText(app, busySpinStatus, labelText)
            arguments
                app(1,1) lvd_EditRectangularSensorGUI_App
                busySpinStatus(1,1) logical
                labelText(1,1) string
            end

            app.BusySpinIcon.Visible = busySpinStatus;
            app.StatusLabel.Text = labelText;
            drawnow;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            centerUIFigure(app.EditRectangularSensorGUI);
            applySelectedThemeToApp(app);

            app.sensor = varargin{1};
            app.lvdData = varargin{2};
            app.output = varargin{3};

            app.populateGUI();

            uiwait(app.EditRectangularSensorGUI);
        end

        % Value changed function: SensorHalfAzAngleText
        function SensorHalfAzAngleTextValueChanged(app, event)
            newInput = app.SensorHalfAzAngleText.Value;
            newInput = attemptStrEval(newInput);
            app.SensorHalfAzAngleText.Value = newInput;

            app.updateDisplayAxes();
        end

        % Value changed function: SensorMaxRangeText
        function SensorMaxRangeTextValueChanged(app, event)
            newInput = app.SensorMaxRangeText.Value;
            newInput = attemptStrEval(newInput);
            app.SensorMaxRangeText.Value = newInput;

            app.updateDisplayAxes();
        end

        % Value changed function: PointingModelCombo
        function PointingModelComboValueChanged(app, event)
            enum = app.PointingModelCombo.Value;

            if(enum == SensorSteeringModelEnum.FixedInCoordSys)
                numCoordSys = app.lvdData.geometry.coordSyses.getNumCoordSyses();

                if(numCoordSys <= 0)
                    app.PointingModelCombo.Value = event.PreviousValue;
                    uialert(app.EditRectangularSensorGUI, 'In order to use the Fixed in Coordinate System pointing model, there must be at least one Geometric Coordinate System in the scenario.', 'No Coordiante Systems Found', 'Icon',"error");
                    return;
                end
            end

            app.updateDisplayAxes();
        end

        % Button pushed function: EditPointingModelButton
        function EditPointingModelButtonPushed(app, event)
            app.getSelectedSteeringModel().openEditDialog();

            app.updateDisplayAxes();
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButtonPushed(app, event)
            errMsg = validateInputs(app);

            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.EditRectangularSensorGUI, errMsg, 'Invalid Sensor Inputs', "Icon","error");
            end
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            close(app.EditRectangularSensorGUI);
        end

        % Value changed function: SensorColorCombo
        function SensorColorComboValueChanged(app, event)
            app.updateDisplayAxes();
        end

        % Value changed function: SensorAlphaSlider
        function SensorAlphaSliderValueChanged(app, event)
            app.updateDisplayAxes();
        end

        % Value changed function: ShowMeshEdgesCheckbox
        function ShowMeshEdgesCheckboxValueChanged(app, event)
            app.updateDisplayAxes();
        end

        % Value changed function: SensorHalfDecAngleText
        function SensorHalfDecAngleTextValueChanged(app, event)
            newInput = app.SensorHalfDecAngleText.Value;
            newInput = attemptStrEval(newInput);
            app.SensorHalfDecAngleText.Value = newInput;

            app.updateDisplayAxes();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditRectangularSensorGUI = uifigure('Visible', 'off');
            app.EditRectangularSensorGUI.Name = 'Edit Sensor';
            app.EditRectangularSensorGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout11 = uigridlayout(app.EditRectangularSensorGUI);
            app.GridLayout = uigridlayout(app.GridLayout11);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Sensor';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButtonPushed, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.Panel = uipanel(app.GridLayout7);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Name';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4 = uigridlayout(app.uipanel2);
            app.sensorNameLabel = uilabel(app.GridLayout4);
            app.sensorNameLabel.Text = 'Sensor Name';
            app.sensorNameText = uieditfield(app.GridLayout4, 'text');
            app.sensorNameText.Tooltip = {'The name of this sensor.'};
            app.sensorNameText.Value = 'POINT NAME';
            app.InitialSensorDefinitionPanel = uipanel(app.GridLayout3);
            app.InitialSensorDefinitionPanel.Title = 'Initial Sensor Definition';
            app.GridLayout5 = uigridlayout(app.InitialSensorDefinitionPanel);
            app.text5 = uilabel(app.GridLayout5);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Sensor Max. Range';
            app.text18 = uilabel(app.GridLayout5);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Sensor Azimuth Angle';
            app.SensorHalfAzAngleText = uieditfield(app.GridLayout5, 'text');
            app.SensorHalfAzAngleText.ValueChangedFcn = createCallbackFcn(app, @SensorHalfAzAngleTextValueChanged, true);
            app.SensorHalfAzAngleText.Tooltip = {'The angle from the center line (or "boresight") of the sensor to the edge of the cone.'};
            app.text6 = uilabel(app.GridLayout5);
            app.text6.Tag = 'text6';
            app.text6.Text = 'deg';
            app.text6_2 = uilabel(app.GridLayout5);
            app.text6_2.Tag = 'text6';
            app.text6_2.Text = 'km';
            app.SensorMaxRangeText = uieditfield(app.GridLayout5, 'text');
            app.SensorMaxRangeText.ValueChangedFcn = createCallbackFcn(app, @SensorMaxRangeTextValueChanged, true);
            app.SensorMaxRangeText.Tooltip = {'The maximum range at which this sensor can "see" targets.'};
            app.text5_2 = uilabel(app.GridLayout5);
            app.text5_2.Tag = 'text5';
            app.text5_2.Text = 'Sensor Active?';
            app.SensorActiveCheckbox = uicheckbox(app.GridLayout5);
            app.SensorActiveCheckbox.Text = '';
            app.text6_3 = uilabel(app.GridLayout5);
            app.text6_3.Tag = 'text6';
            app.text6_3.Text = 'deg';
            app.text18_2 = uilabel(app.GridLayout5);
            app.text18_2.Tag = 'text18';
            app.text18_2.Text = 'Sensor Elevation Angle';
            app.SensorHalfDecAngleText = uieditfield(app.GridLayout5, 'text');
            app.SensorHalfDecAngleText.ValueChangedFcn = createCallbackFcn(app, @SensorHalfDecAngleTextValueChanged, true);
            app.SensorHalfDecAngleText.Tooltip = {'The angle from the center line (or "boresight") of the sensor to the edge of the cone.'};
            app.Image5 = uiimage(app.GridLayout5);
            app.Image5.ImageSource = 'line.png';
            app.Image6 = uiimage(app.GridLayout5);
            app.Image6.ImageSource = 'power(1).png';
            app.Image7 = uiimage(app.GridLayout5);
            app.Image7.ImageSource = 'angle.png';
            app.Image8 = uiimage(app.GridLayout5);
            app.Image8.ImageSource = 'obtuse-angle.png';
            app.InitialPointingModelPanel = uipanel(app.GridLayout3);
            app.InitialPointingModelPanel.Title = 'Initial Pointing Model';
            app.GridLayout6 = uigridlayout(app.InitialPointingModelPanel);
            app.PointingModelCombo = uidropdown(app.GridLayout6);
            app.PointingModelCombo.ValueChangedFcn = createCallbackFcn(app, @PointingModelComboValueChanged, true);
            app.PointingModelCombo.Tooltip = {'Defines the model that dictates how the sensor will be pointed in space.'};
            app.EditPointingModelButton = uibutton(app.GridLayout6, 'push');
            app.EditPointingModelButton.ButtonPushedFcn = createCallbackFcn(app, @EditPointingModelButtonPushed, true);
            app.EditPointingModelButton.Icon = 'blue_cone.png';
            app.EditPointingModelButton.Text = 'Edit Pointing Model';
            app.uipanel2_2 = uipanel(app.GridLayout3);
            app.uipanel2_2.Title = 'Origin';
            app.uipanel2_2.Tag = 'uipanel2';
            app.GridLayout4_2 = uigridlayout(app.uipanel2_2);
            app.sensorNameLabel_2 = uilabel(app.GridLayout4_2);
            app.sensorNameLabel_2.Text = 'Sensor Origin';
            app.SensorOriginCombo = uidropdown(app.GridLayout4_2);
            app.SensorOriginCombo.Tooltip = {'The origin point of the sensor.  Must be a Geometric Point from the Geometry system.'};
            app.Image = uiimage(app.GridLayout4_2);
            app.Image.ImageSource = 'circle(1).png';
            app.DisplayPropertiesPanel = uipanel(app.GridLayout3);
            app.DisplayPropertiesPanel.Title = 'Display Properties';
            app.GridLayout10 = uigridlayout(app.DisplayPropertiesPanel);
            app.sensorNameLabel_3 = uilabel(app.GridLayout10);
            app.sensorNameLabel_3.Text = 'Sensor Color';
            app.sensorNameLabel_4 = uilabel(app.GridLayout10);
            app.sensorNameLabel_4.Text = 'Sensor Transparacy';
            app.SensorAlphaSlider = uislider(app.GridLayout10);
            app.SensorAlphaSlider.Limits = [0 1];
            app.SensorAlphaSlider.ValueChangedFcn = createCallbackFcn(app, @SensorAlphaSliderValueChanged, true);
            app.SensorAlphaSlider.Tooltip = {'The transparency of the sensor''s mesh.  0 is completely transparent and 1 is completely opaque.'};
            app.SensorAlphaSlider.Value = 0.3;
            app.SensorColorCombo = uidropdown(app.GridLayout10);
            app.SensorColorCombo.ValueChangedFcn = createCallbackFcn(app, @SensorColorComboValueChanged, true);
            app.SensorColorCombo.Tooltip = {'The color of the sensor''s mesh when displayed.'};
            app.sensorNameLabel_5 = uilabel(app.GridLayout10);
            app.sensorNameLabel_5.Text = 'Show Mesh Edges';
            app.ShowMeshEdgesCheckbox = uicheckbox(app.GridLayout10);
            app.ShowMeshEdgesCheckbox.ValueChangedFcn = createCallbackFcn(app, @ShowMeshEdgesCheckboxValueChanged, true);
            app.ShowMeshEdgesCheckbox.Text = '';
            app.Image2 = uiimage(app.GridLayout10);
            app.Image2.ImageSource = 'color_wheel.png';
            app.Image3 = uiimage(app.GridLayout10);
            app.Image3.ImageSource = 'transparency(1).png';
            app.Image4 = uiimage(app.GridLayout10);
            app.Image4.ImageSource = 'curve.png';
            app.DisplayPanel = uipanel(app.GridLayout7);
            app.DisplayPanel.Title = 'Display';
            app.GridLayout9 = uigridlayout(app.DisplayPanel);
            app.DisplayAxes = uiaxes(app.GridLayout9);
            app.DisplayAxes.XTickLabel = '';
            app.DisplayAxes.YTickLabel = '';
            app.DisplayAxes.ZTickLabel = '';
            app.Panel_2 = uipanel(app.GridLayout11);
            app.GridLayout12 = uigridlayout(app.Panel_2);
            app.BusySpinIcon = uiimage(app.GridLayout12);
            app.BusySpinIcon.Visible = 'off';
            app.BusySpinIcon.ImageSource = 'Spin-1s-200px.gif';
            app.StatusLabel = uilabel(app.GridLayout12);
            % Show the figure after all components are created
            app.EditRectangularSensorGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditRectangularSensorGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditRectangularSensorGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditRectangularSensorGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditRectangularSensorGUI)
        end
    end
end