classdef lvd_EditSensorsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditSensorsGUI      matlab.ui.Figure
        text2               matlab.ui.control.Label
        GridLayout          matlab.ui.container.GridLayout
        closeButton         matlab.ui.control.Button
        Panel               matlab.ui.container.Panel
        GridLayout2         matlab.ui.container.GridLayout
        SensorsListBox      matlab.ui.control.ListBox
        GridLayout3         matlab.ui.container.GridLayout
        RemoveSensorButton  matlab.ui.control.Button
        AddSensorButton     matlab.ui.control.Button
    end


    properties (Access = private)
        lvdData LvdData
    end

    methods (Access = private)
        function populateGUI(app)
            app.setListboxStr();
        end

        function [listBoxStr, sensors] = setListboxStr(app)
            [listBoxStr, sensors] = app.lvdData.sensors.getListboxStr();
            app.SensorsListBox.Items = listBoxStr;
            app.SensorsListBox.ItemsData = sensors;

            num = numel(sensors);
            if(num <= 0)
                app.RemoveSensorButton.Enable = 'off';
            else
                app.RemoveSensorButton.Enable = 'on';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            centerUIFigure(app.EditSensorsGUI);
            applySelectedThemeToApp(app);

            app.lvdData = varargin{1};

            app.populateGUI();

            uiwait(app.EditSensorsGUI);
        end

        % Button pushed function: AddSensorButton
        function AddSensorButtonPushed(app, event)
            listboxStr = SensorEnum.getListBoxStr();
            out = AppDesignerGUIOutput();
            listdlgARH_App('ListString', listboxStr, ...
                'SelectionMode', 'single', ...
                'ListSize', [300 300], ...
                'InitialValue',1, ...
                'Name', 'Select New Sensor Type', ...
                'PromptString', 'Select the type of the sensor to be created:', ...
                'out',out);
            Selection = out.output{1};
            ok = out.output{2};

            if(ok)
                enum = SensorEnum.getEnumForListboxStr(listboxStr{Selection});

                switch enum
                    case SensorEnum.ConicalSensor
                        numPoints = app.lvdData.geometry.points.getNumPoints();
                        if(numPoints >= 1)
                            origin = app.lvdData.geometry.points.getPointAtInd(1);
                            steeringModel = FixedInVehicleFrameSensorSteeringModel(0, 0, 0, app.lvdData);
                            newSensor = ConicalSensor('Untitled Sensor', deg2rad(10), 1000, origin, steeringModel, app.lvdData);

                        else
                            errMsg = 'Sensors originate at a Geometric Point.  Create at least one Geometric Point first.';
                            uialert(app.EditSensorsGUI, errMsg, 'Cannot Add Sensor', 'Icon',"error");
                            return;
                        end
                        
                    case SensorEnum.RectangularSensor
                        numPoints = app.lvdData.geometry.points.getNumPoints();
                        if(numPoints >= 1)
                            origin = app.lvdData.geometry.points.getPointAtInd(1);
                            steeringModel = FixedInVehicleFrameSensorSteeringModel(0, 0, 0, app.lvdData);
                            newSensor = RectangularSensor('Untitled Sensor', deg2rad(10), deg2rad(5), 1000, origin, steeringModel, app.lvdData);

                        else
                            errMsg = 'Sensors originate at a Geometric Point.  Create at least one Geometric Point first.';
                            uialert(app.EditSensorsGUI, errMsg, 'Cannot Add Sensor', 'Icon',"error");
                            return;
                        end
                        
                    otherwise
                        error('Unknown Sensor Type: %s', enum.name)
                end

                useTF = newSensor.openEditDialog();

                if(useTF)
                    app.lvdData.sensors.addSensor(newSensor);

                    app.setListboxStr();
                end
            end
        end

        % Button pushed function: RemoveSensorButton
        function RemoveSensorButtonPushed(app, event)
            selSensor = app.SensorsListBox.Value;

            tf = selSensor.isInUse(app.lvdData);

            if(tf == false)
                app.lvdData.sensors.removeSensor(selSensor);

                app.lvdData.viewSettings.removeSensorFromViewProfiles(selSensor);

                app.setListboxStr();
            else
                msg = sprintf('Could not delete the sensor "%s" because it is in use elsewhere (event actions, etc).  Remove the sensor dependencies before attempting to delete the sensor.', selSensor.getName());
                uialert(app.EditSensorsGUI, msg, 'Cannot Delete Sensor',"Modal",true);
            end
        end

        % Window button down function: EditSensorsGUI
        function EditSensorsGUIWindowButtonDown(app, event)
            selType = app.EditSensorsGUI.SelectionType;
            curObj = app.EditSensorsGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.SensorsListBox && numel(app.SensorsListBox.ItemsData) > 0)
                    selSensor = app.SensorsListBox.Value;
                    selSensor.openEditDialog();

                    app.setListboxStr();
                end
            end
        end

        % Window key press function: EditSensorsGUI
        function EditSensorsGUIWindowKeyPress(app, event)
            switch(event.Key)
                case 'return'
                    close(app.EditSensorsGUI);
                case 'enter'
                    close(app.EditSensorsGUI);
                case 'escape'
                    close(app.EditSensorsGUI);
            end
        end

        % Button pushed function: closeButton
        function closeButtonPushed(app, event)
            close(app.EditSensorsGUI);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditSensorsGUI = uifigure('Visible', 'off');
            app.EditSensorsGUI.Name = 'Edit Sensors';
            app.EditSensorsGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.EditSensorsGUI.WindowButtonDownFcn = createCallbackFcn(app, @EditSensorsGUIWindowButtonDown, true);
            app.EditSensorsGUI.WindowKeyPressFcn = createCallbackFcn(app, @EditSensorsGUIWindowKeyPress, true);
            app.GridLayout = uigridlayout(app.EditSensorsGUI);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.AddSensorButton = uibutton(app.GridLayout3, 'push');
            app.AddSensorButton.ButtonPushedFcn = createCallbackFcn(app, @AddSensorButtonPushed, true);
            app.AddSensorButton.Icon = 'add.png';
            app.AddSensorButton.Text = 'Add Sensor';
            app.RemoveSensorButton = uibutton(app.GridLayout3, 'push');
            app.RemoveSensorButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSensorButtonPushed, true);
            app.RemoveSensorButton.Icon = 'remove.png';
            app.RemoveSensorButton.Text = 'Remove Sensor';
            app.SensorsListBox = uilistbox(app.GridLayout2);
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButtonPushed, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Icon = 'close_window(1).png';
            app.closeButton.Text = 'Close';
            app.text2 = uilabel(app.EditSensorsGUI);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Sensors';
            % Show the figure after all components are created
            app.EditSensorsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditSensorsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditSensorsGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditSensorsGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditSensorsGUI)
        end
    end
end