classdef lvd_EditFixedInCoordSysSensorSteeringModelGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditSensorPointingModelUIFigure  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        Panel                    matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        CoordinateSystemPanel    matlab.ui.container.Panel
        GridLayout5              matlab.ui.container.GridLayout
        CoordSysCombo            matlab.ui.control.DropDown
        PointingDefinitionPanel  matlab.ui.container.Panel
        GridLayout4              matlab.ui.container.GridLayout
        RollText                 matlab.ui.control.EditField
        DeclinationText          matlab.ui.control.EditField
        RightAscText             matlab.ui.control.EditField
        text6_3                  matlab.ui.control.Label
        text6_2                  matlab.ui.control.Label
        text6                    matlab.ui.control.Label
        text5_3                  matlab.ui.control.Label
        text5_2                  matlab.ui.control.Label
        text5                    matlab.ui.control.Label
        GridLayout2              matlab.ui.container.GridLayout
        cancelButton             matlab.ui.control.Button
        saveAndCloseButton       matlab.ui.control.Button
        text2                    matlab.ui.control.Label
    end

    properties (Access = private)
        steeringModel FixedInCoordSysSensorSteeringModel
        lvdData LvdData
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app)
            app.RightAscText.Value = fullAccNum2Str(rad2deg(app.steeringModel.rhtAsc));
            app.DeclinationText.Value = fullAccNum2Str(rad2deg(app.steeringModel.dec));
            app.RollText.Value = fullAccNum2Str(rad2deg(app.steeringModel.roll));

            [listboxStr, coordSyses] = app.lvdData.geometry.coordSyses.getListboxStr();
            app.CoordSysCombo.Items = listboxStr;
            app.CoordSysCombo.ItemsData = coordSyses;
            app.CoordSysCombo.Value = app.steeringModel.coordSys;
        end

        function saveAndClose(app)
            app.steeringModel.rhtAsc = deg2rad(str2double(app.RightAscText.Value));
            app.steeringModel.dec = deg2rad(str2double(app.DeclinationText.Value));
            app.steeringModel.roll = deg2rad(str2double(app.RollText.Value));

            app.steeringModel.coordSys = app.CoordSysCombo.Value;

            app.output.output = {true};
            close(app.EditSensorPointingModelUIFigure);
        end

        function errMsg = validateInputs(app)
            errMsg = {};

            value = str2double(app.RightAscText.Value);
            enteredStr = app.RightAscText.Value;
            numberName = 'Right Ascension Angle';
            lb = -360;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(app.DeclinationText.Value);
            enteredStr = app.DeclinationText.Value;
            numberName = 'Declination Angle';
            lb = -90;
            ub = 90;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(app.RollText.Value);
            enteredStr = app.RollText.Value;
            numberName = 'Roll Angle';
            lb = -360;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            centerUIFigure(app.EditSensorPointingModelUIFigure);
            applySelectedThemeToApp(app);

            app.steeringModel = varargin{1};
            app.lvdData = varargin{2};
            app.output = varargin{3};

            app.populateGUI();

            uiwait(app.EditSensorPointingModelUIFigure);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButtonPushed(app, event)
            errMsg = validateInputs(app);

            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.EditSensorPointingModelUIFigure, errMsg, 'Invalid Pointing Model Inputs', "Icon","error");
            end
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            close(app.EditSensorPointingModelUIFigure);
        end

        % Value changed function: RightAscText
        function RightAscTextValueChanged(app, event)
            newInput = app.RightAscText.Value;
            newInput = attemptStrEval(newInput);
            app.RightAscText.Value = newInput;
        end

        % Value changed function: DeclinationText
        function DeclinationTextValueChanged(app, event)
            newInput = app.DeclinationText.Value;
            newInput = attemptStrEval(newInput);
            app.DeclinationText.Value = newInput;
        end

        % Value changed function: RollText
        function RollTextValueChanged(app, event)
            newInput = app.RollText.Value;
            newInput = attemptStrEval(newInput);
            app.RollText.Value = newInput;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditSensorPointingModelUIFigure = uifigure('Visible', 'off');
            app.EditSensorPointingModelUIFigure.Name = 'Edit Sensor Pointing Model';
            app.EditSensorPointingModelUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout = uigridlayout(app.EditSensorPointingModelUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Text = 'Edit Sensor Pointing Model';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButtonPushed, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Text = 'Cancel';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.PointingDefinitionPanel = uipanel(app.GridLayout3);
            app.PointingDefinitionPanel.Title = 'Pointing Definition';
            app.GridLayout4 = uigridlayout(app.PointingDefinitionPanel);
            app.text5 = uilabel(app.GridLayout4);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Right Ascension';
            app.text5_2 = uilabel(app.GridLayout4);
            app.text5_2.Tag = 'text5';
            app.text5_2.Text = 'Declination';
            app.text5_3 = uilabel(app.GridLayout4);
            app.text5_3.Tag = 'text5';
            app.text5_3.Text = 'Roll Angle';
            app.text6 = uilabel(app.GridLayout4);
            app.text6.Tag = 'text6';
            app.text6.Text = 'deg';
            app.text6_2 = uilabel(app.GridLayout4);
            app.text6_2.Tag = 'text6';
            app.text6_2.Text = 'deg';
            app.text6_3 = uilabel(app.GridLayout4);
            app.text6_3.Tag = 'text6';
            app.text6_3.Text = 'deg';
            app.RightAscText = uieditfield(app.GridLayout4, 'text');
            app.RightAscText.ValueChangedFcn = createCallbackFcn(app, @RightAscTextValueChanged, true);
            app.RightAscText.Tooltip = {'The angle of right ascension (angle in X-Y plane) towards which the sensor will steer.'};
            app.DeclinationText = uieditfield(app.GridLayout4, 'text');
            app.DeclinationText.ValueChangedFcn = createCallbackFcn(app, @DeclinationTextValueChanged, true);
            app.DeclinationText.Tooltip = {'The angle of declination (angle out of the X-Y plane) towards which the sensor will steer.'};
            app.RollText = uieditfield(app.GridLayout4, 'text');
            app.RollText.ValueChangedFcn = createCallbackFcn(app, @RollTextValueChanged, true);
            app.RollText.Tooltip = {'The angle around the sensor''s boresight vector that the sensor will be rolled around.'};
            app.CoordinateSystemPanel = uipanel(app.GridLayout3);
            app.CoordinateSystemPanel.Title = 'Coordinate System';
            app.GridLayout5 = uigridlayout(app.CoordinateSystemPanel);
            app.CoordSysCombo = uidropdown(app.GridLayout5);
            app.CoordSysCombo.Tooltip = {'The coordinate system in which the pointing defintion will be applied.'};
            % Show the figure after all components are created
            app.EditSensorPointingModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditFixedInCoordSysSensorSteeringModelGUI_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EditSensorPointingModelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditSensorPointingModelUIFigure)
        end
    end
end