classdef lvd_EditLatLongCircleGridSensorTargetGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditSensorTargetUIFigure  matlab.ui.Figure
        GridLayout12              matlab.ui.container.GridLayout
        Panel_4                   matlab.ui.container.Panel
        GridLayout14              matlab.ui.container.GridLayout
        StatusLabel               matlab.ui.control.Label
        BusySpinIcon              matlab.ui.control.Image
        GridLayout13              matlab.ui.container.GridLayout
        GridLayout                matlab.ui.container.GridLayout
        Panel                     matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        GridLayout5               matlab.ui.container.GridLayout
        DisplayPropertiesPanel    matlab.ui.container.Panel
        GridLayout8               matlab.ui.container.GridLayout
        GridLayout7               matlab.ui.container.GridLayout
        Image4                    matlab.ui.control.Image
        Image3                    matlab.ui.control.Image
        NotFoundEdgeColorCombo    matlab.ui.control.DropDown
        FoundEdgeColorCombo       matlab.ui.control.DropDown
        NotFoundFaceColorCombo    matlab.ui.control.DropDown
        FoundFaceColorCombo       matlab.ui.control.DropDown
        targetNameLabel_14        matlab.ui.control.Label
        targetNameLabel_13        matlab.ui.control.Label
        targetNameLabel_16        matlab.ui.control.Label
        targetNameLabel_12        matlab.ui.control.Label
        GridLayout9               matlab.ui.container.GridLayout
        Image2                    matlab.ui.control.Image
        Image                     matlab.ui.control.Image
        MarkerSizeText            matlab.ui.control.EditField
        MarkerShapeCombo          matlab.ui.control.DropDown
        targetNameLabel_11        matlab.ui.control.Label
        targetNameLabel_10        matlab.ui.control.Label
        TargetDefinitionPanel     matlab.ui.container.Panel
        GridLayout6               matlab.ui.container.GridLayout
        arcLengthText             matlab.ui.control.EditField
        targetNameLabel_17        matlab.ui.control.Label
        text6_8                   matlab.ui.control.Label
        altitudeText              matlab.ui.control.EditField
        numRadialPtsText          matlab.ui.control.EditField
        numCircumPtsText          matlab.ui.control.EditField
        offsetText                matlab.ui.control.EditField
        radiusText                matlab.ui.control.EditField
        centerLatText             matlab.ui.control.EditField
        centerLongText            matlab.ui.control.EditField
        BodyCombo                 matlab.ui.control.DropDown
        text6_7                   matlab.ui.control.Label
        text6_6                   matlab.ui.control.Label
        text6_5                   matlab.ui.control.Label
        text6_4                   matlab.ui.control.Label
        text6_3                   matlab.ui.control.Label
        text6_2                   matlab.ui.control.Label
        text6                     matlab.ui.control.Label
        targetNameLabel_9         matlab.ui.control.Label
        targetNameLabel_8         matlab.ui.control.Label
        targetNameLabel_7         matlab.ui.control.Label
        targetNameLabel_6         matlab.ui.control.Label
        targetNameLabel_5         matlab.ui.control.Label
        targetNameLabel_4         matlab.ui.control.Label
        targetNameLabel_3         matlab.ui.control.Label
        targetNameLabel_2         matlab.ui.control.Label
        uipanel2                  matlab.ui.container.Panel
        GridLayout4_2             matlab.ui.container.GridLayout
        targetNameText            matlab.ui.control.EditField
        targetNameLabel           matlab.ui.control.Label
        GridDisplayPanel          matlab.ui.container.Panel
        GridLayout4               matlab.ui.container.GridLayout
        DrawGridon2DMapButton     matlab.ui.control.Button
        Panel_3                   matlab.ui.container.Panel
        GridLayout11              matlab.ui.container.GridLayout
        DisplayLatLongAxes        matlab.ui.control.UIAxes
        Panel_2                   matlab.ui.container.Panel
        GridLayout10              matlab.ui.container.GridLayout
        Display3DGridAxes         matlab.ui.control.UIAxes
        GridLayout2               matlab.ui.container.GridLayout
        cancelButton              matlab.ui.control.Button
        saveAndCloseButton        matlab.ui.control.Button
        text2                     matlab.ui.control.Label
    end


    properties (Access = private)
        target BodyFixedCircleGridTargetModel
        lvdData LvdData
        output AppDesignerGUIOutput

        markerFound
        markerNotFound

        markerFound3D
        markerNotFound3D
    end

    methods (Access = private)
        function populateGUI(app)
            app.targetNameText.Value = app.target.name;

            [listBoxStr, bodyInfos] = app.lvdData.celBodyData.getListboxStr();
            app.BodyCombo.Items = listBoxStr;
            app.BodyCombo.ItemsData = bodyInfos;
            app.BodyCombo.Value = app.target.bodyInfo;

            app.centerLongText.Value = fullAccNum2Str(rad2deg(app.target.longCenter));
            app.centerLatText.Value = fullAccNum2Str(rad2deg(app.target.latCenter));
            app.radiusText.Value = fullAccNum2Str(rad2deg(app.target.radius));
            app.offsetText.Value = fullAccNum2Str(rad2deg(app.target.arcOffset));
            app.arcLengthText.Value = fullAccNum2Str(rad2deg(app.target.arcAngle));
            app.numCircumPtsText.Value = fullAccNum2Str(app.target.numPtsCircumference);
            app.numRadialPtsText.Value = fullAccNum2Str(app.target.numPtsRadial);
            app.altitudeText.Value = fullAccNum2Str(app.target.altitude);

            [listBoxStr, m] = MarkerStyleEnum.getListboxStr();
            app.MarkerShapeCombo.Items = listBoxStr;
            app.MarkerShapeCombo.ItemsData = m;
            app.MarkerShapeCombo.Value = app.target.markerShape;

            app.MarkerSizeText.Value = fullAccNum2Str(app.target.markerSize);

            lvdSetupColorPicker(app.EditSensorTargetUIFigure, app.GridLayout7, 2, 3, app.FoundFaceColorCombo, lvd_colorSpecToRGB(app.target.markerFoundFaceColor), 'Pick a color.', 'foundFaceColor', @(rgb) app.updateDispAxes());

            lvdSetupColorPicker(app.EditSensorTargetUIFigure, app.GridLayout7, 3, 3, app.FoundEdgeColorCombo, lvd_colorSpecToRGB(app.target.markerFoundEdgeColor), 'Pick a color.', 'foundEdgeColor', @(rgb) app.updateDispAxes());

            lvdSetupColorPicker(app.EditSensorTargetUIFigure, app.GridLayout7, 2, 4, app.NotFoundFaceColorCombo, lvd_colorSpecToRGB(app.target.markerNotFoundFaceColor), 'Pick a color.', 'notFoundFaceColor', @(rgb) app.updateDispAxes());

            lvdSetupColorPicker(app.EditSensorTargetUIFigure, app.GridLayout7, 3, 4, app.NotFoundEdgeColorCombo, lvd_colorSpecToRGB(app.target.markerNotFoundEdgeColor), 'Pick a color.', 'notFoundEdgeColor', @(rgb) app.updateDispAxes());

            app.updateDispAxes();
        end

        function saveAndClose(app)
            app.target.name = app.targetNameText.Value;

            bodyInfo = app.BodyCombo.Value;

            longCenter = deg2rad(str2double(app.centerLongText.Value));
            latCenter = deg2rad(str2double(app.centerLatText.Value));
            radius = deg2rad(str2double(app.radiusText.Value));
            arcOffset = deg2rad(str2double(app.offsetText.Value));
            arcAngle = deg2rad(str2double(app.arcLengthText.Value));
            numPtsCircumference = str2double(app.numCircumPtsText.Value);
            numPtsRadial = str2double(app.numRadialPtsText.Value);
            altitude = str2double(app.altitudeText.Value);

            app.target.setGridPointsFromInputs(bodyInfo, longCenter, latCenter, radius, arcOffset, arcAngle, numPtsCircumference, numPtsRadial, altitude);

            app.target.markerShape = app.MarkerShapeCombo.Value;
            app.target.markerSize = str2double(app.MarkerSizeText.Value);

            app.target.markerFoundFaceColor = lvdGetColorPickerRGB(app.EditSensorTargetUIFigure, 'foundFaceColor', app.target.markerFoundFaceColor);
            app.target.markerNotFoundFaceColor = lvdGetColorPickerRGB(app.EditSensorTargetUIFigure, 'notFoundFaceColor', app.target.markerNotFoundFaceColor);
            app.target.markerFoundEdgeColor = lvdGetColorPickerRGB(app.EditSensorTargetUIFigure, 'foundEdgeColor', app.target.markerFoundEdgeColor);
            app.target.markerNotFoundEdgeColor = lvdGetColorPickerRGB(app.EditSensorTargetUIFigure, 'notFoundEdgeColor', app.target.markerNotFoundEdgeColor);

            app.output.output = {true};
            close(app.EditSensorTargetUIFigure);
        end

        function errMsg = validateInputs(app)
            errMsg = {};

            value = str2double(app.centerLongText.Value);
            enteredStr = app.centerLongText.Value;
            numberName = 'Center Longitude';
            lb = -360;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(app.centerLatText.Value);
            enteredStr = app.centerLatText.Value;
            numberName = 'Center Latitude';
            lb = -90;
            ub = 90;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(app.radiusText.Value);
            enteredStr = app.radiusText.Value;
            numberName = 'Radius';
            lb = 0.001;
            ub = 180;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(app.offsetText.Value);
            enteredStr = app.offsetText.Value;
            numberName = 'Rotational Offset';
            lb = -360;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(app.arcLengthText.Value);
            enteredStr = app.arcLengthText.Value;
            numberName = 'Arc Length';
            lb = 0.001;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(app.numCircumPtsText.Value);
            enteredStr = app.numCircumPtsText.Value;
            numberName = 'Number of Circumference Points';
            lb = 2;
            ub = Inf;
            isInt = true;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(app.numRadialPtsText.Value);
            enteredStr = app.numRadialPtsText.Value;
            numberName = 'Number of Radial Points';
            lb = 2;
            ub = Inf;
            isInt = true;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(app.altitudeText.Value);
            enteredStr = app.altitudeText.Value;
            numberName = 'Altitude';
            lb = 0.001;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(app.MarkerSizeText.Value);
            enteredStr = app.MarkerSizeText.Value;
            numberName = 'Marker Size';
            lb = 1;
            ub = 20;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(strtrim(app.targetNameText.Value)))
                errMsg{end+1} = 'Target name must contain more than white space and must not be empty.';
            end
        end

        function updateDispAxes(app)
            app.setTaskbarIconText(true, 'Building Target Model');
            dummyTarget = BodyFixedCircleGridTargetModel('Dummy', app.BodyCombo.Value, deg2rad(str2double(app.centerLongText.Value)), deg2rad(str2double(app.centerLatText.Value)), ...
                deg2rad(str2double(app.radiusText.Value)), deg2rad(str2double(app.offsetText.Value)), deg2rad(str2double(app.arcLengthText.Value)), ...
                str2double(app.numCircumPtsText.Value), str2double(app.numRadialPtsText.Value), str2double(app.altitudeText.Value), ...
                app.lvdData);

            dummyTarget.markerShape = app.MarkerShapeCombo.Value;
            dummyTarget.markerSize = str2double(app.MarkerSizeText.Value);

            dummyTarget.markerFoundFaceColor = lvdGetColorPickerRGB(app.EditSensorTargetUIFigure, 'foundFaceColor', app.target.markerFoundFaceColor);
            dummyTarget.markerNotFoundFaceColor = lvdGetColorPickerRGB(app.EditSensorTargetUIFigure, 'notFoundFaceColor', app.target.markerNotFoundFaceColor);
            dummyTarget.markerFoundEdgeColor = lvdGetColorPickerRGB(app.EditSensorTargetUIFigure, 'foundEdgeColor', app.target.markerFoundEdgeColor);
            dummyTarget.markerNotFoundEdgeColor = lvdGetColorPickerRGB(app.EditSensorTargetUIFigure, 'notFoundEdgeColor', app.target.markerNotFoundEdgeColor);

            app.setTaskbarIconText(true, 'Updating Lat/Long Grid Display');
            [lats, longs] = app.updateLatLongDispAxes(dummyTarget);

            app.setTaskbarIconText(true, 'Updating 3D Grid Display');
            app.update3DGridAxes(dummyTarget, lats, longs);

            app.setTaskbarIconText(false, '');
        end

        function [lats, longs] = updateLatLongDispAxes(app, dummyTarget)
            bodyInfo = dummyTarget.bodyInfo;

            hold(app.DisplayLatLongAxes,'on');
            if(isempty(app.markerFound))
                if(not(isempty(bodyInfo.surftexturefile)))
                    try
                        imshow(bodyInfo.surftexturefile, 'Parent',app.DisplayLatLongAxes);
                    catch
                        app.DisplayLatLongAxes.XLim = [-180 180];
                        app.DisplayLatLongAxes.YLim = [-90 90];
                        grid(app.DisplayLatLongAxes, 'minor');
                    end
                else
                    app.DisplayLatLongAxes.XLim = [-180 180];
                    app.DisplayLatLongAxes.YLim = [-90 90];
                    grid(app.DisplayLatLongAxes, 'minor');
                end
                axis(app.DisplayLatLongAxes, 'on');
                axis(app.DisplayLatLongAxes, 'tight');
            end

            xlim = app.DisplayLatLongAxes.XLim;
            ylim = app.DisplayLatLongAxes.YLim;

            rVectECEF = dummyTarget.rVectECEF;
            rNorm = vecNormARH(rVectECEF);
            longs = AngleZero2Pi(atan2(rVectECEF(2,:),rVectECEF(1,:)) + pi);
            lats = -1*angleNegPiToPi((pi/2 - acos(rVectECEF(3,:)./rNorm)));

            longsScaled1 = (longs - 0)/(2*pi - 0);
            latsScaled1 = (lats - (-pi/2))/(pi/2 - (-pi/2));

            longsScaled2 = longsScaled1*(xlim(2) - xlim(1)) + xlim(1);
            latsScaled2 = latsScaled1*(ylim(2) - ylim(1)) + ylim(1);

            bool = true(size(longsScaled2));
            bool(2:2:numel(longsScaled2)) = false;

            shape = dummyTarget.getMarkerShape().shape;
            foundMarkerFaceColor = lvd_colorSpecToRGB(dummyTarget.getFoundMarkerFaceColor());
            foundMarkerEdgeolor = lvd_colorSpecToRGB(dummyTarget.getFoundMarkerEdgeColor());
            notFoundMarkerFaceColor = lvd_colorSpecToRGB(dummyTarget.getNotFoundMarkerFaceColor());
            notFoundMarkerEdgeolor = lvd_colorSpecToRGB(dummyTarget.getNotFoundMarkerEdgeColor());
            markerSize = dummyTarget.getMarkerSize();

            if(isempty(app.markerFound))
                gridLinesLong = linspace(xlim(1), xlim(2), 13);
                for(i=1:length(gridLinesLong))
                    x = gridLinesLong(i);
                    plot(app.DisplayLatLongAxes, [x, x], [ylim(1), ylim(2)], 'Color',[1,1,1,0.3], "LineStyle","-", "LineWidth",1.0);
                end
                xticks(app.DisplayLatLongAxes, gridLinesLong);
                xticklabels(app.DisplayLatLongAxes, string(linspace(-180,180,13)));

                gridLinesLat = linspace(ylim(1), ylim(2), 7);
                for(i=1:length(gridLinesLat))
                    y = gridLinesLat(i);
                    plot(app.DisplayLatLongAxes, [xlim(1), xlim(2)], [y, y], 'Color',[1,1,1,0.3], "LineStyle","-", "LineWidth",1.0);
                end
                yticks(app.DisplayLatLongAxes, gridLinesLat);
                yticklabels(app.DisplayLatLongAxes, string(linspace(90,-90,7)));

                app.markerFound = plot(app.DisplayLatLongAxes, longsScaled2(bool), latsScaled2(bool),   'LineStyle','none', 'Marker',shape, 'MarkerFaceColor',foundMarkerFaceColor, 'MarkerEdgeColor',foundMarkerEdgeolor, 'MarkerSize',markerSize);
                app.markerNotFound = plot(app.DisplayLatLongAxes, longsScaled2(~bool), latsScaled2(~bool), 'LineStyle','none', 'Marker',shape, 'MarkerFaceColor',notFoundMarkerFaceColor, 'MarkerEdgeColor',notFoundMarkerEdgeolor, 'MarkerSize',markerSize);
            else
                app.markerFound.XData = longsScaled2(bool);
                app.markerFound.YData = latsScaled2(bool);
                app.markerFound.Marker = shape;
                app.markerFound.MarkerFaceColor = foundMarkerFaceColor;
                app.markerFound.MarkerEdgeColor = foundMarkerEdgeolor;
                app.markerFound.MarkerSize = markerSize;

                app.markerNotFound.XData = longsScaled2(~bool);
                app.markerNotFound.YData = latsScaled2(~bool);
                app.markerNotFound.Marker = shape;
                app.markerNotFound.MarkerFaceColor = notFoundMarkerFaceColor;
                app.markerNotFound.MarkerEdgeColor = notFoundMarkerEdgeolor;
                app.markerNotFound.MarkerSize = markerSize;
            end

            hold(app.DisplayLatLongAxes,'off');
        end

        function update3DGridAxes(app, dummyTarget, lats, longs)
            bodyInfo = dummyTarget.bodyInfo;
            viewFrame = bodyInfo.getBodyCenteredInertialFrame();

            if(isempty(app.markerFound3D))
                [~, hCBodySurfXForm] = ma_initOrbPlot([], app.Display3DGridAxes, bodyInfo);

                M = getBodyXformMatrix(0, bodyInfo, viewFrame);
                hCBodySurfXForm.Matrix = M;
            end

            lats = -1*lats;     %undo correction from other method
            longs = longs - pi; %undo correction from other method

            times = zeros(size(lats));
            alts = dummyTarget.altitude * ones(size(lats));
            zeroArr = zeros(size(lats));
            for(i=1:length(lats)) %#ok<*NO4LP>
                geoElems(i) = GeographicElementSet(times(i), lats(i), longs(i), alts(i), zeroArr(i), zeroArr(i), zeroArr(i), bodyInfo.getBodyFixedFrame()); %#ok<*AGROW>
            end
            cartElem = convertToFrame(convertToCartesianElementSet(geoElems), viewFrame);

            rVect = [cartElem.rVect];

            bool = true(1, width(rVect));
            bool(2:2:numel(bool)) = false;

            shape = dummyTarget.getMarkerShape().shape;
            foundMarkerFaceColor = lvd_colorSpecToRGB(dummyTarget.getFoundMarkerFaceColor());
            foundMarkerEdgeolor = lvd_colorSpecToRGB(dummyTarget.getFoundMarkerEdgeColor());
            notFoundMarkerFaceColor = lvd_colorSpecToRGB(dummyTarget.getNotFoundMarkerFaceColor());
            notFoundMarkerEdgeolor = lvd_colorSpecToRGB(dummyTarget.getNotFoundMarkerEdgeColor());
            markerSize = dummyTarget.getMarkerSize();

            hold(app.Display3DGridAxes, 'on');
            if(isempty(app.markerFound3D))
                app.markerFound3D = plot3(app.Display3DGridAxes, rVect(1,bool), rVect(2,bool), rVect(3,bool), 'LineStyle','none');
                app.markerNotFound3D = plot3(app.Display3DGridAxes, rVect(1,~bool), rVect(2,~bool), rVect(3,~bool), 'LineStyle','none');

                view(app.Display3DGridAxes, -37.5, 30);
            else
                app.markerFound3D.XData = rVect(1,bool);
                app.markerFound3D.YData = rVect(2,bool);
                app.markerFound3D.ZData = rVect(3,bool);

                app.markerNotFound3D.XData = rVect(1,~bool);
                app.markerNotFound3D.YData = rVect(2,~bool);
                app.markerNotFound3D.ZData = rVect(3,~bool);
            end

            app.markerFound3D.Marker = shape;
            app.markerFound3D.MarkerFaceColor = foundMarkerFaceColor;
            app.markerFound3D.MarkerEdgeColor = foundMarkerEdgeolor;
            app.markerFound3D.MarkerSize = markerSize;

            app.markerNotFound3D.Marker = shape;
            app.markerNotFound3D.MarkerFaceColor = notFoundMarkerFaceColor;
            app.markerNotFound3D.MarkerEdgeColor = notFoundMarkerEdgeolor;
            app.markerNotFound3D.MarkerSize = markerSize;

            hold(app.Display3DGridAxes, 'off');
        end

        function clearStoredDispElems(app)
            app.markerFound = [];
            app.markerNotFound = [];
            app.markerFound3D = [];
            app.markerNotFound3D = [];
        end

        function setTaskbarIconText(app, busySpinStatus, labelText)
            arguments
                app(1,1) lvd_EditLatLongCircleGridSensorTargetGUI_App
                busySpinStatus(1,1) logical
                labelText(1,1) string
            end

            app.BusySpinIcon.Visible = busySpinStatus;
            app.StatusLabel.Text = labelText;
            drawnow;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            centerUIFigure(app.EditSensorTargetUIFigure);
            applySelectedThemeToApp(app);

            app.target = varargin{1};
            app.lvdData = varargin{2};
            app.output = varargin{3};

            app.populateGUI();

            uiwait(app.EditSensorTargetUIFigure);
        end

        % Value changed function: centerLongText
        function centerLongTextValueChanged(app, event)
            newInput = app.centerLongText.Value;
            newInput = attemptStrEval(newInput);
            app.centerLongText.Value = newInput;

            app.updateDispAxes();
        end

        % Value changed function: centerLatText
        function centerLatTextValueChanged(app, event)
            newInput = app.centerLatText.Value;
            newInput = attemptStrEval(newInput);
            app.centerLatText.Value = newInput;

            app.updateDispAxes();
        end

        % Value changed function: radiusText
        function radiusTextValueChanged(app, event)
            newInput = app.radiusText.Value;
            newInput = attemptStrEval(newInput);
            app.radiusText.Value = newInput;

            app.updateDispAxes();
        end

        % Value changed function: offsetText
        function offsetTextValueChanged(app, event)
            newInput = app.offsetText.Value;
            newInput = attemptStrEval(newInput);
            app.offsetText.Value = newInput;

            app.updateDispAxes();
        end

        % Value changed function: numCircumPtsText
        function numCircumPtsTextValueChanged(app, event)
            newInput = app.numCircumPtsText.Value;
            newInput = attemptStrEval(newInput);
            app.numCircumPtsText.Value = newInput;

            app.updateDispAxes();
        end

        % Value changed function: numRadialPtsText
        function numRadialPtsTextValueChanged(app, event)
            newInput = app.numRadialPtsText.Value;
            newInput = attemptStrEval(newInput);
            app.numRadialPtsText.Value = newInput;

            app.updateDispAxes();
        end

        % Value changed function: altitudeText
        function altitudeTextValueChanged(app, event)
            newInput = app.altitudeText.Value;
            newInput = attemptStrEval(newInput);
            app.altitudeText.Value = newInput;

            app.updateDispAxes();
        end

        % Value changed function: MarkerSizeText
        function MarkerSizeTextValueChanged(app, event)
            newInput = app.MarkerSizeText.Value;
            newInput = attemptStrEval(newInput);
            app.MarkerSizeText.Value = newInput;

            app.updateDispAxes();
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButtonPushed(app, event)
            errMsg = app.validateInputs();

            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.EditSensorTargetUIFigure, errMsg, 'Invalid Target Inputs', "Icon","error");
            end
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            close(app.EditSensorTargetUIFigure);
        end

        % Value changed function: BodyCombo
        function BodyComboValueChanged(app, event)
            cla(app.DisplayLatLongAxes);
            cla(app.Display3DGridAxes);

            app.clearStoredDispElems();
            app.updateDispAxes();
        end

        % Value changed function: MarkerShapeCombo
        function MarkerShapeComboValueChanged(app, event)
            app.updateDispAxes();
        end

        % Value changed function: FoundFaceColorCombo
        function FoundFaceColorComboValueChanged(app, event)
            app.updateDispAxes();
        end

        % Value changed function: NotFoundFaceColorCombo
        function NotFoundFaceColorComboValueChanged(app, event)
            app.updateDispAxes();
        end

        % Value changed function: FoundEdgeColorCombo
        function FoundEdgeColorComboValueChanged(app, event)
            app.updateDispAxes();
        end

        % Value changed function: NotFoundEdgeColorCombo
        function NotFoundEdgeColorComboValueChanged(app, event)
            app.updateDispAxes();
        end

        % Value changed function: arcLengthText
        function arcLengthTextValueChanged(app, event)
            newInput = app.arcLengthText.Value;
            newInput = attemptStrEval(newInput);
            app.arcLengthText.Value = newInput;

            app.updateDispAxes();
        end

        % Button pushed function: DrawGridon2DMapButton
        function DrawGridon2DMapButtonPushed(app, event)
            roi = drawcircle(app.DisplayLatLongAxes);
            center = roi.Center;
            radius = roi.Radius;

            xlim = app.DisplayLatLongAxes.XLim;
            ylim = app.DisplayLatLongAxes.YLim;

            longsScaled1 = (center(1) - xlim(1))/(xlim(2) - xlim(1));
            latsScaled1 = (center(2)  - ylim(1))/(ylim(2) - ylim(1));
            radScaled1 = (radius  - xlim(1))/(xlim(2) - xlim(1));

            longsScaled2 = (longsScaled1*(2*pi - 0) + 0) - pi;
            latsScaled2 = -1*(latsScaled1*(pi/2 - (-pi/2)) + (-pi/2));
            radScaled2 = (radScaled1*(2*pi - 0) + 0);

            app.centerLongText.Value = fullAccNum2Str(rad2deg(longsScaled2));
            app.centerLatText.Value = fullAccNum2Str(rad2deg(latsScaled2));
            app.radiusText.Value = fullAccNum2Str(rad2deg(radScaled2));

            delete(roi);
            app.updateDispAxes();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditSensorTargetUIFigure = uifigure('Visible', 'off');
            app.EditSensorTargetUIFigure.Name = 'Edit Sensor Target';
            app.EditSensorTargetUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout12 = uigridlayout(app.EditSensorTargetUIFigure);
            app.GridLayout13 = uigridlayout(app.GridLayout12);
            app.GridLayout = uigridlayout(app.GridLayout13);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Sensor Target';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButtonPushed, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'remove.png';
            app.cancelButton.Text = 'Cancel';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.GridDisplayPanel = uipanel(app.GridLayout3);
            app.GridDisplayPanel.Title = 'Grid Display';
            app.GridLayout4 = uigridlayout(app.GridDisplayPanel);
            app.Panel_2 = uipanel(app.GridLayout4);
            app.GridLayout10 = uigridlayout(app.Panel_2);
            app.Display3DGridAxes = uiaxes(app.GridLayout10);
            app.Panel_3 = uipanel(app.GridLayout4);
            app.GridLayout11 = uigridlayout(app.Panel_3);
            app.DisplayLatLongAxes = uiaxes(app.GridLayout11);
            xlabel(app.DisplayLatLongAxes, 'Longitude [deg]')
            ylabel(app.DisplayLatLongAxes, 'Latitude [deg]')
            app.DrawGridon2DMapButton = uibutton(app.GridLayout4, 'push');
            app.DrawGridon2DMapButton.ButtonPushedFcn = createCallbackFcn(app, @DrawGridon2DMapButtonPushed, true);
            app.DrawGridon2DMapButton.Text = 'Draw Grid on 2D Map';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.uipanel2 = uipanel(app.GridLayout5);
            app.uipanel2.Title = 'Name';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4_2 = uigridlayout(app.uipanel2);
            app.targetNameLabel = uilabel(app.GridLayout4_2);
            app.targetNameLabel.Text = 'Target Name';
            app.targetNameText = uieditfield(app.GridLayout4_2, 'text');
            app.targetNameText.Tooltip = {'The name of this sensor.'};
            app.targetNameText.Value = 'POINT NAME';
            app.TargetDefinitionPanel = uipanel(app.GridLayout5);
            app.TargetDefinitionPanel.Title = 'Target Definition';
            app.GridLayout6 = uigridlayout(app.TargetDefinitionPanel);
            app.targetNameLabel_2 = uilabel(app.GridLayout6);
            app.targetNameLabel_2.Text = 'Celestial Body';
            app.targetNameLabel_3 = uilabel(app.GridLayout6);
            app.targetNameLabel_3.Text = 'Center Longitude';
            app.targetNameLabel_4 = uilabel(app.GridLayout6);
            app.targetNameLabel_4.Text = 'Center Latitude';
            app.targetNameLabel_5 = uilabel(app.GridLayout6);
            app.targetNameLabel_5.Text = 'Radius';
            app.targetNameLabel_6 = uilabel(app.GridLayout6);
            app.targetNameLabel_6.Text = 'Rotational Offset';
            app.targetNameLabel_7 = uilabel(app.GridLayout6);
            app.targetNameLabel_7.Text = 'Num. of Circumference Points';
            app.targetNameLabel_8 = uilabel(app.GridLayout6);
            app.targetNameLabel_8.Text = 'Num. of Radial Points';
            app.targetNameLabel_9 = uilabel(app.GridLayout6);
            app.targetNameLabel_9.Text = 'Altitude';
            app.text6 = uilabel(app.GridLayout6);
            app.text6.Tag = 'text6';
            app.text6.Text = 'deg';
            app.text6_2 = uilabel(app.GridLayout6);
            app.text6_2.Tag = 'text6';
            app.text6_2.Text = 'deg';
            app.text6_3 = uilabel(app.GridLayout6);
            app.text6_3.Tag = 'text6';
            app.text6_3.Text = 'deg';
            app.text6_4 = uilabel(app.GridLayout6);
            app.text6_4.Tag = 'text6';
            app.text6_4.Text = 'deg';
            app.text6_5 = uilabel(app.GridLayout6);
            app.text6_5.Tag = 'text6';
            app.text6_5.Text = '';
            app.text6_6 = uilabel(app.GridLayout6);
            app.text6_6.Tag = 'text6';
            app.text6_6.Text = '';
            app.text6_7 = uilabel(app.GridLayout6);
            app.text6_7.Tag = 'text6';
            app.text6_7.Text = 'km';
            app.BodyCombo = uidropdown(app.GridLayout6);
            app.BodyCombo.ValueChangedFcn = createCallbackFcn(app, @BodyComboValueChanged, true);
            app.centerLongText = uieditfield(app.GridLayout6, 'text');
            app.centerLongText.ValueChangedFcn = createCallbackFcn(app, @centerLongTextValueChanged, true);
            app.centerLatText = uieditfield(app.GridLayout6, 'text');
            app.centerLatText.ValueChangedFcn = createCallbackFcn(app, @centerLatTextValueChanged, true);
            app.radiusText = uieditfield(app.GridLayout6, 'text');
            app.radiusText.ValueChangedFcn = createCallbackFcn(app, @radiusTextValueChanged, true);
            app.offsetText = uieditfield(app.GridLayout6, 'text');
            app.offsetText.ValueChangedFcn = createCallbackFcn(app, @offsetTextValueChanged, true);
            app.numCircumPtsText = uieditfield(app.GridLayout6, 'text');
            app.numCircumPtsText.ValueChangedFcn = createCallbackFcn(app, @numCircumPtsTextValueChanged, true);
            app.numRadialPtsText = uieditfield(app.GridLayout6, 'text');
            app.numRadialPtsText.ValueChangedFcn = createCallbackFcn(app, @numRadialPtsTextValueChanged, true);
            app.altitudeText = uieditfield(app.GridLayout6, 'text');
            app.altitudeText.ValueChangedFcn = createCallbackFcn(app, @altitudeTextValueChanged, true);
            app.text6_8 = uilabel(app.GridLayout6);
            app.text6_8.Tag = 'text6';
            app.text6_8.Text = 'deg';
            app.targetNameLabel_17 = uilabel(app.GridLayout6);
            app.targetNameLabel_17.Text = 'Arc Length';
            app.arcLengthText = uieditfield(app.GridLayout6, 'text');
            app.arcLengthText.ValueChangedFcn = createCallbackFcn(app, @arcLengthTextValueChanged, true);
            app.DisplayPropertiesPanel = uipanel(app.GridLayout5);
            app.DisplayPropertiesPanel.Title = 'Display Properties';
            app.GridLayout8 = uigridlayout(app.DisplayPropertiesPanel);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.targetNameLabel_10 = uilabel(app.GridLayout9);
            app.targetNameLabel_10.Text = 'Marker Shape';
            app.targetNameLabel_11 = uilabel(app.GridLayout9);
            app.targetNameLabel_11.Text = 'Marker Size';
            app.MarkerShapeCombo = uidropdown(app.GridLayout9);
            app.MarkerShapeCombo.ValueChangedFcn = createCallbackFcn(app, @MarkerShapeComboValueChanged, true);
            app.MarkerSizeText = uieditfield(app.GridLayout9, 'text');
            app.MarkerSizeText.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeTextValueChanged, true);
            app.Image = uiimage(app.GridLayout9);
            app.Image.ImageSource = 'diamond.png';
            app.Image2 = uiimage(app.GridLayout9);
            app.Image2.ImageSource = 'size.png';
            app.GridLayout7 = uigridlayout(app.GridLayout8);
            app.targetNameLabel_12 = uilabel(app.GridLayout7);
            app.targetNameLabel_12.Text = 'Target Point Found';
            app.targetNameLabel_16 = uilabel(app.GridLayout7);
            app.targetNameLabel_16.Text = 'Target Point Not Found';
            app.targetNameLabel_13 = uilabel(app.GridLayout7);
            app.targetNameLabel_13.Text = 'Face Color';
            app.targetNameLabel_14 = uilabel(app.GridLayout7);
            app.targetNameLabel_14.Text = 'Edge Color';
            app.FoundFaceColorCombo = uidropdown(app.GridLayout7);
            app.FoundFaceColorCombo.ValueChangedFcn = createCallbackFcn(app, @FoundFaceColorComboValueChanged, true);
            app.NotFoundFaceColorCombo = uidropdown(app.GridLayout7);
            app.NotFoundFaceColorCombo.ValueChangedFcn = createCallbackFcn(app, @NotFoundFaceColorComboValueChanged, true);
            app.FoundEdgeColorCombo = uidropdown(app.GridLayout7);
            app.FoundEdgeColorCombo.ValueChangedFcn = createCallbackFcn(app, @FoundEdgeColorComboValueChanged, true);
            app.NotFoundEdgeColorCombo = uidropdown(app.GridLayout7);
            app.NotFoundEdgeColorCombo.ValueChangedFcn = createCallbackFcn(app, @NotFoundEdgeColorComboValueChanged, true);
            app.Image3 = uiimage(app.GridLayout7);
            app.Image3.ImageSource = 'color_wheel.png';
            app.Image4 = uiimage(app.GridLayout7);
            app.Image4.ImageSource = 'color_wheel.png';
            app.Panel_4 = uipanel(app.GridLayout12);
            app.GridLayout14 = uigridlayout(app.Panel_4);
            app.BusySpinIcon = uiimage(app.GridLayout14);
            app.BusySpinIcon.Visible = 'off';
            app.BusySpinIcon.ImageSource = 'Spin-1s-200px.gif';
            app.StatusLabel = uilabel(app.GridLayout14);
            % Show the figure after all components are created
            app.EditSensorTargetUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditLatLongCircleGridSensorTargetGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditSensorTargetUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditSensorTargetUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditSensorTargetUIFigure)
        end
    end
end