classdef lvd_EditPointSensorTargetGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditSensorTargetUIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        Panel                     matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        GridLayout5               matlab.ui.container.GridLayout
        TargetDefinitionPanel     matlab.ui.container.Panel
        GridLayout14              matlab.ui.container.GridLayout
        Image5                    matlab.ui.control.Image
        TargetPointCombo          matlab.ui.control.DropDown
        targetNameLabel_17        matlab.ui.control.Label
        DisplayPropertiesPanel    matlab.ui.container.Panel
        GridLayout8               matlab.ui.container.GridLayout
        GridLayout7               matlab.ui.container.GridLayout
        Image4                    matlab.ui.control.Image
        Image3                    matlab.ui.control.Image
        NotFoundEdgeColorCombo    matlab.ui.control.DropDown
        FoundEdgeColorCombo       matlab.ui.control.DropDown
        NotFoundFaceColorCombo    matlab.ui.control.DropDown
        FoundFaceColorCombo       matlab.ui.control.DropDown
        targetNameLabel_14        matlab.ui.control.Label
        targetNameLabel_13        matlab.ui.control.Label
        targetNameLabel_16        matlab.ui.control.Label
        targetNameLabel_12        matlab.ui.control.Label
        GridLayout9               matlab.ui.container.GridLayout
        Image2                    matlab.ui.control.Image
        Image                     matlab.ui.control.Image
        MarkerSizeText            matlab.ui.control.EditField
        MarkerShapeCombo          matlab.ui.control.DropDown
        targetNameLabel_11        matlab.ui.control.Label
        targetNameLabel_10        matlab.ui.control.Label
        uipanel2                  matlab.ui.container.Panel
        GridLayout4_2             matlab.ui.container.GridLayout
        targetNameText            matlab.ui.control.EditField
        targetNameLabel           matlab.ui.control.Label
        GridLayout2               matlab.ui.container.GridLayout
        cancelButton              matlab.ui.control.Button
        saveAndCloseButton        matlab.ui.control.Button
        text2                     matlab.ui.control.Label
    end


    properties (Access = private)
        target PointSensorTargetModel
        lvdData LvdData
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app)
            app.targetNameText.Value = app.target.name;

            [listboxStr, points] = app.lvdData.geometry.points.getListboxStr();
            app.TargetPointCombo.Items = listboxStr;
            app.TargetPointCombo.ItemsData = points;
            app.TargetPointCombo.Value = app.target.point;

            [listBoxStr, m] = MarkerStyleEnum.getListboxStr();
            app.MarkerShapeCombo.Items = listBoxStr;
            app.MarkerShapeCombo.ItemsData = m;
            app.MarkerShapeCombo.Value = app.target.markerShape;

            app.MarkerSizeText.Value = fullAccNum2Str(app.target.markerSize);

            lvdSetupColorPicker(app.EditSensorTargetUIFigure, app.GridLayout7, 2, 3, app.FoundFaceColorCombo, lvd_colorSpecToRGB(app.target.markerFoundFaceColor), 'Pick a color.', 'foundFaceColor');

            lvdSetupColorPicker(app.EditSensorTargetUIFigure, app.GridLayout7, 3, 3, app.FoundEdgeColorCombo, lvd_colorSpecToRGB(app.target.markerFoundEdgeColor), 'Pick a color.', 'foundEdgeColor');

            lvdSetupColorPicker(app.EditSensorTargetUIFigure, app.GridLayout7, 2, 4, app.NotFoundFaceColorCombo, lvd_colorSpecToRGB(app.target.markerNotFoundFaceColor), 'Pick a color.', 'notFoundFaceColor');

            lvdSetupColorPicker(app.EditSensorTargetUIFigure, app.GridLayout7, 3, 4, app.NotFoundEdgeColorCombo, lvd_colorSpecToRGB(app.target.markerNotFoundEdgeColor), 'Pick a color.', 'notFoundEdgeColor');
        end

        function saveAndClose(app)
            app.target.name = app.targetNameText.Value;

            app.target.point = app.TargetPointCombo.Value;

            app.target.markerShape = app.MarkerShapeCombo.Value;
            app.target.markerSize = str2double(app.MarkerSizeText.Value);

            app.target.markerFoundFaceColor = lvdGetColorPickerRGB(app.EditSensorTargetUIFigure, 'foundFaceColor', app.target.markerFoundFaceColor);
            app.target.markerNotFoundFaceColor = lvdGetColorPickerRGB(app.EditSensorTargetUIFigure, 'notFoundFaceColor', app.target.markerNotFoundFaceColor);
            app.target.markerFoundEdgeColor = lvdGetColorPickerRGB(app.EditSensorTargetUIFigure, 'foundEdgeColor', app.target.markerFoundEdgeColor);
            app.target.markerNotFoundEdgeColor = lvdGetColorPickerRGB(app.EditSensorTargetUIFigure, 'notFoundEdgeColor', app.target.markerNotFoundEdgeColor);

            app.output.output = {true};
            close(app.EditSensorTargetUIFigure);
        end

        function errMsg = validateInputs(app)
            errMsg = {};

            value = str2double(app.MarkerSizeText.Value);
            enteredStr = app.MarkerSizeText.Value;
            numberName = 'Marker Size';
            lb = 1;
            ub = 20;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(strtrim(app.targetNameText.Value)))
                errMsg{end+1} = 'Target name must contain more than white space and must not be empty.';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            centerUIFigure(app.EditSensorTargetUIFigure);
            applySelectedThemeToApp(app);

            app.target = varargin{1};
            app.lvdData = varargin{2};
            app.output = varargin{3};

            app.populateGUI();

            uiwait(app.EditSensorTargetUIFigure);
        end

        % Value changed function: MarkerSizeText
        function MarkerSizeTextValueChanged(app, event)
            newInput = app.MarkerSizeText.Value;
            newInput = attemptStrEval(newInput);
            app.MarkerSizeText.Value = newInput;
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButtonPushed(app, event)
            errMsg = app.validateInputs();

            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.EditSensorTargetUIFigure, errMsg, 'Invalid Target Inputs', "Icon","error");
            end
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            close(app.EditSensorTargetUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditSensorTargetUIFigure = uifigure('Visible', 'off');
            app.EditSensorTargetUIFigure.Name = 'Edit Sensor Target';
            app.EditSensorTargetUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout = uigridlayout(app.EditSensorTargetUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Sensor Target';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButtonPushed, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.uipanel2 = uipanel(app.GridLayout5);
            app.uipanel2.Title = 'Name';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4_2 = uigridlayout(app.uipanel2);
            app.targetNameLabel = uilabel(app.GridLayout4_2);
            app.targetNameLabel.Text = 'Target Name';
            app.targetNameText = uieditfield(app.GridLayout4_2, 'text');
            app.targetNameText.Tooltip = {'The name of this sensor.'};
            app.targetNameText.Value = 'POINT NAME';
            app.DisplayPropertiesPanel = uipanel(app.GridLayout5);
            app.DisplayPropertiesPanel.Title = 'Display Properties';
            app.GridLayout8 = uigridlayout(app.DisplayPropertiesPanel);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.targetNameLabel_10 = uilabel(app.GridLayout9);
            app.targetNameLabel_10.Text = 'Marker Shape';
            app.targetNameLabel_11 = uilabel(app.GridLayout9);
            app.targetNameLabel_11.Text = 'Marker Size';
            app.MarkerShapeCombo = uidropdown(app.GridLayout9);
            app.MarkerSizeText = uieditfield(app.GridLayout9, 'text');
            app.MarkerSizeText.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeTextValueChanged, true);
            app.Image = uiimage(app.GridLayout9);
            app.Image.ImageSource = 'diamond.png';
            app.Image2 = uiimage(app.GridLayout9);
            app.Image2.ImageSource = 'size.png';
            app.GridLayout7 = uigridlayout(app.GridLayout8);
            app.targetNameLabel_12 = uilabel(app.GridLayout7);
            app.targetNameLabel_12.Text = 'Target Point Found';
            app.targetNameLabel_16 = uilabel(app.GridLayout7);
            app.targetNameLabel_16.Text = 'Target Point Not Found';
            app.targetNameLabel_13 = uilabel(app.GridLayout7);
            app.targetNameLabel_13.Text = 'Face Color';
            app.targetNameLabel_14 = uilabel(app.GridLayout7);
            app.targetNameLabel_14.Text = 'Edge Color';
            app.FoundFaceColorCombo = uidropdown(app.GridLayout7);
            app.NotFoundFaceColorCombo = uidropdown(app.GridLayout7);
            app.FoundEdgeColorCombo = uidropdown(app.GridLayout7);
            app.NotFoundEdgeColorCombo = uidropdown(app.GridLayout7);
            app.Image3 = uiimage(app.GridLayout7);
            app.Image3.ImageSource = 'color_wheel.png';
            app.Image4 = uiimage(app.GridLayout7);
            app.Image4.ImageSource = 'color_wheel.png';
            app.TargetDefinitionPanel = uipanel(app.GridLayout5);
            app.TargetDefinitionPanel.Title = 'Target Definition';
            app.GridLayout14 = uigridlayout(app.TargetDefinitionPanel);
            app.targetNameLabel_17 = uilabel(app.GridLayout14);
            app.targetNameLabel_17.Text = 'Target Point';
            app.TargetPointCombo = uidropdown(app.GridLayout14);
            app.Image5 = uiimage(app.GridLayout14);
            app.Image5.ImageSource = 'circle(1).png';
            % Show the figure after all components are created
            app.EditSensorTargetUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditPointSensorTargetGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditSensorTargetUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditSensorTargetUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditSensorTargetUIFigure)
        end
    end
end