classdef lvd_EditSensorTargetsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditSensorTargetsGUI  matlab.ui.Figure
        text2                 matlab.ui.control.Label
        GridLayout            matlab.ui.container.GridLayout
        closeButton           matlab.ui.control.Button
        Panel                 matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        SensorTargetsListBox  matlab.ui.control.ListBox
        GridLayout3           matlab.ui.container.GridLayout
        RemoveTargetButton    matlab.ui.control.Button
        AddTargetButton       matlab.ui.control.Button
    end


    properties (Access = private)
        lvdData LvdData
    end

    methods (Access = private)
        function populateGUI(app)
            app.setListboxStr();
        end

        function [listBoxStr, targets] = setListboxStr(app)
            [listBoxStr, targets] = app.lvdData.sensorTgts.getListboxStr();
            app.SensorTargetsListBox.Items = listBoxStr;
            app.SensorTargetsListBox.ItemsData = targets;

            num = numel(targets);
            if(num <= 0)
                app.RemoveTargetButton.Enable = 'off';
            else
                app.RemoveTargetButton.Enable = 'on';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            centerUIFigure(app.EditSensorTargetsGUI);
            applySelectedThemeToApp(app);

            app.lvdData = varargin{1};

            app.populateGUI();

            uiwait(app.EditSensorTargetsGUI);
        end

        % Button pushed function: AddTargetButton
        function AddTargetButtonPushed(app, event)
            listboxStr = SensorTargetEnum.getListBoxStr();
            out = AppDesignerGUIOutput();
            listdlgARH_App('ListString', listboxStr, ...
                'SelectionMode', 'single', ...
                'ListSize', [300 300], ...
                'InitialValue',1, ...
                'Name', 'Select New Sensor Target', ...
                'PromptString', 'Select the type of the target to be created:', ...
                'out',out);
            Selection = out.output{1};
            ok = out.output{2};

            if(ok)
                enum = SensorTargetEnum.getEnumForListboxStr(listboxStr{Selection});

                switch enum
                    case SensorTargetEnum.PointTarget
                        numPoints = app.lvdData.geometry.points.getNumPoints();
                        if(numPoints >= 1)
                            point = app.lvdData.geometry.points.getPointAtInd(1);
                            newTarget = PointSensorTargetModel('Untitled Target', point, app.lvdData);
                            
                        else
                            errMsg = 'Point Targets require at least one Geometric Point to be in the scenario.  Create one first.';
                            uialert(app.EditSensorTargetsGUI, errMsg, 'Cannot Add Target', 'Icon',"error");
                            return;
                        end
                        
                    case SensorTargetEnum.LatLongRectGrid
                        bodyInfo = LvdData.getDefaultInitialBodyInfo(app.lvdData.celBodyData);
                        newTarget = BodyFixedLatLongGridTargetModel('Untitled Target', bodyInfo, deg2rad(-30), deg2rad(-30), deg2rad(30), deg2rad(30), 10, 10, 1, app.lvdData);
                        
                    case SensorTargetEnum.LatLongCircGrid
                        bodyInfo = LvdData.getDefaultInitialBodyInfo(app.lvdData.celBodyData);
                        newTarget = BodyFixedCircleGridTargetModel('Untitled Target', bodyInfo, 0, 0, deg2rad(30), deg2rad(0), deg2rad(360), 10, 10, 1, app.lvdData);
                        
                    otherwise
                        error('Unknown Target Type: %s', enum.name)
                end

                useTF = newTarget.openEditDialog();

                if(useTF)
                    app.lvdData.sensorTgts.addTarget(newTarget);

                    app.setListboxStr();
                end
            end
        end

        % Button pushed function: RemoveTargetButton
        function RemoveTargetButtonPushed(app, event)
            selTarget = app.SensorTargetsListBox.Value;

            tf = selTarget.isInUse(app.lvdData);

            if(tf == false)
                app.lvdData.sensorTgts.removeTarget(selTarget);

                app.lvdData.viewSettings.removeSensorTargetsFromViewProfiles(selTarget);

                app.setListboxStr();
            else
                msg = sprintf('Could not delete the target "%s" because it is in use elsewhere.  Remove the target dependencies before attempting to delete the target.', selTarget.getName());
                uialert(app.EditSensorTargetsGUI, msg, 'Cannot Delete Target',"Modal",true);
            end
        end

        % Window button down function: EditSensorTargetsGUI
        function EditSensorTargetsGUIWindowButtonDown(app, event)
            selType = app.EditSensorTargetsGUI.SelectionType;
            curObj = app.EditSensorTargetsGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.SensorTargetsListBox && numel(app.SensorTargetsListBox.ItemsData) > 0)
                    selTarget = app.SensorTargetsListBox.Value;
                    selTarget.openEditDialog();

                    app.setListboxStr();
                end
            end
        end

        % Window key press function: EditSensorTargetsGUI
        function EditSensorTargetsGUIWindowKeyPress(app, event)
            switch(event.Key)
                case 'return'
                    close(app.EditSensorTargetsGUI);
                case 'enter'
                    close(app.EditSensorTargetsGUI);
                case 'escape'
                    close(app.EditSensorTargetsGUI);
            end
        end

        % Button pushed function: closeButton
        function closeButtonPushed(app, event)
            close(app.EditSensorTargetsGUI);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditSensorTargetsGUI = uifigure('Visible', 'off');
            app.EditSensorTargetsGUI.Name = 'Edit Sensor Targets';
            app.EditSensorTargetsGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.EditSensorTargetsGUI.WindowButtonDownFcn = createCallbackFcn(app, @EditSensorTargetsGUIWindowButtonDown, true);
            app.EditSensorTargetsGUI.WindowKeyPressFcn = createCallbackFcn(app, @EditSensorTargetsGUIWindowKeyPress, true);
            app.GridLayout = uigridlayout(app.EditSensorTargetsGUI);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.AddTargetButton = uibutton(app.GridLayout3, 'push');
            app.AddTargetButton.ButtonPushedFcn = createCallbackFcn(app, @AddTargetButtonPushed, true);
            app.AddTargetButton.Icon = 'add.png';
            app.AddTargetButton.Text = 'Add Target';
            app.RemoveTargetButton = uibutton(app.GridLayout3, 'push');
            app.RemoveTargetButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveTargetButtonPushed, true);
            app.RemoveTargetButton.Icon = 'remove.png';
            app.RemoveTargetButton.Text = 'Remove Target';
            app.SensorTargetsListBox = uilistbox(app.GridLayout2);
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButtonPushed, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Text = 'Close';
            app.text2 = uilabel(app.EditSensorTargetsGUI);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Sensor Targets';
            % Show the figure after all components are created
            app.EditSensorTargetsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditSensorTargetsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditSensorTargetsGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditSensorTargetsGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditSensorTargetsGUI)
        end
    end
end