classdef lvd_Edit2DKerbalWindTunnelDragPropertiesGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditDragPropertiesUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout3                 matlab.ui.container.GridLayout
        WindTunnelDataPanel         matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        DataAxes                    matlab.ui.control.UIAxes
        FlightEnvelopeOutputCSVFilePanel  matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        FileSelector                wt.FileSelector
        GridLayout2                 matlab.ui.container.GridLayout
        cancelButton                matlab.ui.control.Button
        saveCloseButton             matlab.ui.control.Button
        text2                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput
        lvdData LvdData
        dragCoeffModel TwoDimKspWindTunnelDragCoeffModel

        dummyWindTunnelModel TwoDimKspWindTunnelDragCoeffModel
    end
    
    methods (Access = private)
        function populateGUI(app)
            app.FileSelector.Value = app.dragCoeffModel.dataFile;
            app.FileSelector.DefaultDirectory = pwd;

            if(not(isempty(app.dragCoeffModel.speed)) && not(isempty(app.dragCoeffModel.altitude)) && not(isempty(app.dragCoeffModel.data)))
                app.plotWindTunnelData();
            end
        end

        function plotWindTunnelData(app)
            hPrgDlg = uiprogressdlg(app.EditDragPropertiesUIFigure, "Indeterminate","on", "Message","Plotting flight envelope data.", "Title","Edit Drag Properties");

            if(app.FileSelector.ValueIsValidPath)
                dataFile = app.FileSelector.FullPath;
                app.dummyWindTunnelModel = TwoDimKspWindTunnelDragCoeffModel(dataFile);
                app.dummyWindTunnelModel.plotDragEnvelope(app.DataAxes);
            else
                app.dragCoeffModel.plotDragEnvelope(app.DataAxes);
            end

            close(hPrgDlg);
        end

        function errMsg = validateInputs(app)
            errMsg = {};

            if(not(app.FileSelector.ValueIsValidPath))
                errMsg{end+1} = 'The selected Kerbal Wind Tunnel flight envelope data file does not exist.';
            else
                try
                    app.dragCoeffModel.dataFile = app.FileSelector.FullPath;
                    app.dragCoeffModel.clearData();
                    app.dragCoeffModel.createGriddedInterpFromFile();
                catch ME
                    errMsg{end+1} = sprintf("Import of the Kerbal Wind Tunnel flight evelope data failed.  Message: \n\n%s", ME.message);
                end
            end
        end
        
        function saveAndClose(app)
            app.dragCoeffModel.dataFile = app.FileSelector.FullPath;
            app.dragCoeffModel.clearData();
            app.dragCoeffModel.createGriddedInterpFromFile();

            app.output.output{1} = true;
            close(app.EditDragPropertiesUIFigure);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dragCoeffModel, lvdData, out)
            centerUIFigure(app.EditDragPropertiesUIFigure);
            applySelectedThemeToApp(app);

            app.lvdData = lvdData;
            app.dragCoeffModel = dragCoeffModel;
            app.output = out;

            app.populateGUI();

            uiwait(app.EditDragPropertiesUIFigure);
        end

        % Button pushed function: saveCloseButton
        function saveCloseButtonPushed(app, event)
            errMsg = validateInputs(app);

            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.EditDragPropertiesUIFigure, errMsg, 'Errors were found while editing drag properties.', "Icon","error");
            end
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            app.output.output = {false};
            close(app.EditDragPropertiesUIFigure);
        end

        % Callback function: FileSelector
        function FileSelectorValueChanged(app, event)
            app.plotWindTunnelData();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditDragPropertiesUIFigure = uifigure('Visible', 'off');
            app.EditDragPropertiesUIFigure.Name = 'Edit Drag Properties';
            app.EditDragPropertiesUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout = uigridlayout(app.EditDragPropertiesUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Drag Properties';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveCloseButtonPushed, true);
            app.saveCloseButton.Tag = 'saveCloseButton';
            app.saveCloseButton.Icon = 'save-file.png';
            app.saveCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.FlightEnvelopeOutputCSVFilePanel = uipanel(app.GridLayout3);
            app.FlightEnvelopeOutputCSVFilePanel.Title = 'Flight Envelope Output CSV File';
            app.GridLayout4 = uigridlayout(app.FlightEnvelopeOutputCSVFilePanel);
            app.FileSelector = wt.FileSelector(app.GridLayout4);
            app.FileSelector.ValueChangedFcn = createCallbackFcn(app, @FileSelectorValueChanged, true);
            app.FileSelector.Tooltip = "Select the Kerbal Wind Tunnel drag flight envelope data file here.  Usually the file name is of the format 'vessel_name_Drag.csv'.";
            app.WindTunnelDataPanel = uipanel(app.GridLayout3);
            app.WindTunnelDataPanel.Title = 'Wind Tunnel Data ';
            app.GridLayout5 = uigridlayout(app.WindTunnelDataPanel);
            app.DataAxes = uiaxes(app.GridLayout5);
            title(app.DataAxes, 'Title')
            xlabel(app.DataAxes, 'X')
            ylabel(app.DataAxes, 'Y')
            zlabel(app.DataAxes, 'Z')
            % Show the figure after all components are created
            app.EditDragPropertiesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_Edit2DKerbalWindTunnelDragPropertiesGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditDragPropertiesUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditDragPropertiesUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditDragPropertiesUIFigure)
        end
    end
end