classdef lvd_Edit3DKerbalWindTunnelDragPropertiesGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditDragPropertiesUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout2_2               matlab.ui.container.GridLayout
        cancelButton                matlab.ui.control.Button
        saveCloseButton             matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        WarningsAlertsPanel         matlab.ui.container.Panel
        GridLayout11                matlab.ui.container.GridLayout
        WarningsAlertsTable         matlab.ui.control.Table
        GridLayout3                 matlab.ui.container.GridLayout
        GridLayout8                 matlab.ui.container.GridLayout
        GridLayout9                 matlab.ui.container.GridLayout
        removeAoASliceButton        matlab.ui.control.Button
        addAoASliceButton           matlab.ui.control.Button
        SlicesListBox               matlab.ui.control.ListBox
        FlightEnvelopeOutputPanel   matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        DataPropertiesPanel         matlab.ui.container.Panel
        GridLayout10                matlab.ui.container.GridLayout
        AoASliceDataTable           matlab.ui.control.Table
        WindTunnelDataPanel         matlab.ui.container.Panel
        GridLayout7                 matlab.ui.container.GridLayout
        SliceDispAxes               matlab.ui.control.UIAxes
        FlightEnvelopeOutputCSVFilePanel  matlab.ui.container.Panel
        GridLayout6                 matlab.ui.container.GridLayout
        FileSelector                wt.FileSelector
        GridLayout4                 matlab.ui.container.GridLayout
        AoAText                     matlab.ui.control.EditField
        utUnitLabel                 matlab.ui.control.Label
        AngleofAttackLabel          matlab.ui.control.Label
        text2                       matlab.ui.control.Label
    end

    properties (Access = private)
        output AppDesignerGUIOutput
        lvdData LvdData
        dragCoeffModel ThreeDimKspWindTunnelDragCoeffModel

        dummyModel ThreeDimKspWindTunnelDragCoeffModel
    end

    methods (Access = private)
        function populateGUI(app)
            app.populateListbox();
            app.populateSliceDataUI();

            app.populateWarningAlertTable();
        end

        function populateListbox(app, valueSlice)
            arguments
                app(1,1) lvd_Edit3DKerbalWindTunnelDragPropertiesGUI_App
                valueSlice ThreeDimKWTDragCoefficientModelSlice = ThreeDimKWTDragCoefficientModelSlice.empty(1,0);
            end

            [listBoxStr, slices] = app.dummyModel.getListBoxStrs();
            app.SlicesListBox.Items = listBoxStr;
            app.SlicesListBox.ItemsData = slices;

            if(isempty(valueSlice))
                app.SlicesListBox.Value = slices(1);
            else
                app.SlicesListBox.Value = valueSlice;
            end
        end

        function populateSliceDataUI(app)
            slice = app.SlicesListBox.Value;

            app.AoAText.Value = fullAccNum2Str(rad2deg(slice.aoa));
            app.FileSelector.Value = slice.twoDSlice.dataFile;
            app.AoASliceDataTable.Data = slice.getSliceDataTableData();

            slice.twoDSlice.plotDragEnvelope(app.SliceDispAxes);
        end
        
        function errMsg = validateSliceInputs(app)
            errMsg = {};

            area = str2double(app.AoAText.Value);
            enteredStr = app.AoAText.Value;
            numberName = 'Angle of Attack';
            lb = 0;
            ub = 90;
            isInt = false;
            errMsg = validateNumber(area, numberName, lb, ub, isInt, errMsg, enteredStr);
        end
        
        function populateWarningAlertTable(app)
            [slicesGoodTf, messagesAll] = app.dummyModel.getStatusOfSlices();

            rowNames = string.empty(1,0);
            tableMessages = string.empty(1,0);
            rowGoodTf = logical.empty(1,0);
            for(i=1:length(slicesGoodTf)) %#ok<*NO4LP>
                slice = app.dummyModel.slices(i);

                sliceGoodTf = slicesGoodTf(i);
                messages = messagesAll{i};

                for(j=1:length(messages))
                    aoaDeg = rad2deg(slice.aoa);

                    rowNames(end+1) = sprintf('%0.3f deg', aoaDeg); %#ok<AGROW> 
                    tableMessages(end+1) = messages(j); %#ok<AGROW> 
                    rowGoodTf(end+1) = sliceGoodTf; %#ok<AGROW> 
                end
            end

            app.WarningsAlertsTable.RowName = rowNames;
            app.WarningsAlertsTable.Data = tableMessages(:);
            
            removeStyle(app.WarningsAlertsTable);
            for(i=1:length(rowGoodTf))
                if(rowGoodTf(i))
                    s = uistyle("Icon","success", "IconAlignment",'leftmargin');
                else
                    s = uistyle("Icon","warning", "IconAlignment",'leftmargin');
                end

                addStyle(app.WarningsAlertsTable, s, 'row', i);
            end
        end
        
        function saveAndClose(app)
            [slicesGoodTf, ~] = app.dummyModel.getStatusOfSlices();

            if(all(slicesGoodTf == true))
                app.dragCoeffModel.slices = app.dummyModel.slices;
                app.dragCoeffModel.createGriddedInterpFromSlices();
    
                app.output.output{1} = true;
                close(app.EditDragPropertiesUIFigure);
            else
                errMsg = 'Cannot save drag coefficient model in this state: there are still outstanding warnings and errors to resolve. Resolve the warnings and errors shown in the table first before saving.';
                uialert(app.EditDragPropertiesUIFigure, errMsg, "Edit Drag Properties", "Icon","error");
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dragCoeffModel, lvdData, out)
            centerUIFigure(app.EditDragPropertiesUIFigure);
            applySelectedThemeToApp(app);

            app.lvdData = lvdData;
            app.dragCoeffModel = dragCoeffModel;
            app.dummyModel = getArrayFromByteStream(getByteStreamFromArray(dragCoeffModel));
            app.output = out;

            app.populateGUI();

            uiwait(app.EditDragPropertiesUIFigure);
        end

        % Value changed function: AoAText
        function AoATextValueChanged(app, event)
            newInput = app.AoAText.Value;
            newInput = attemptStrEval(newInput);
            app.AoAText.Value = newInput;

            errMsg = app.validateSliceInputs();

            slice = app.SlicesListBox.Value;
            if(isempty(errMsg))
                slice.aoa = deg2rad(str2double(app.AoAText.Value));
                app.populateListbox(slice);

            else
                app.AoAText.Value = fullAccNum2Str(rad2deg(slice.aoa));
                uialert(app.EditDragPropertiesUIFigure, errMsg, "Edit Drag Properties", "Icon","error");
            end

            app.populateWarningAlertTable();
        end

        % Callback function: FileSelector
        function FileSelectorValueChanged(app, event)
            hPrgDlg = uiprogressdlg(app.EditDragPropertiesUIFigure, "Indeterminate","on", "Message","Plotting flight envelope data.", "Title","Edit Drag Properties");

            slice = app.SlicesListBox.Value;

            if(app.FileSelector.ValueIsValidPath)
                try
                    dataFile = app.FileSelector.FullPath;
                    slice.twoDSlice = TwoDimKspWindTunnelDragCoeffModel(dataFile);
                    slice.twoDSlice.createGriddedInterpFromFile();
    
                    app.populateSliceDataUI();
                catch ME
                    msg = sprintf("There was an error parsing the selected data file.  Message:\n\n%s", ME.message);
                    uialert(app.EditDragPropertiesUIFigure, msg, "Edit Drag Properties", "Icon","error");
                end
            else
                uialert(app.EditDragPropertiesUIFigure, "Data file selected is not a valid path.", "Edit Drag Properties", "Icon","error");
            end

            app.populateWarningAlertTable();

            close(hPrgDlg);
        end

        % Button pushed function: addAoASliceButton
        function addAoASliceButtonPushed(app, event)
            slices = app.dummyModel.slices;
            aoas = [slices.aoa];
            aoaDiffs = diff(aoas);
            
            newAoA = aoas(end) + abs(aoaDiffs(end));
            newSlice = ThreeDimKWTDragCoefficientModelSlice(newAoA, '');
            app.dummyModel.addSlice(newSlice);

            app.populateListbox(newSlice);
            app.populateSliceDataUI();
            app.populateWarningAlertTable();
        end

        % Button pushed function: removeAoASliceButton
        function removeAoASliceButtonPushed(app, event)
            slice = app.SlicesListBox.Value;

            sliceInd = find(slice == app.SlicesListBox.ItemsData,1,'first');

            app.dummyModel.removeSlice(slice);

            if(sliceInd > numel(app.dummyModel.slices))
                sliceInd = numel(app.dummyModel.slices);
            end
            sliceValue = app.dummyModel.slices(sliceInd);           

            hPrgDlg = uiprogressdlg(app.EditDragPropertiesUIFigure, "Indeterminate","on", "Message","Plotting flight envelope data.", "Title","Edit Drag Properties");
            
            app.populateListbox(sliceValue);
            app.populateSliceDataUI();
            app.populateWarningAlertTable();

            close(hPrgDlg);
        end

        % Button pushed function: saveCloseButton
        function saveCloseButtonPushed(app, event)
            app.saveAndClose();
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            app.output.output{1} = false;
            close(app.EditDragPropertiesUIFigure);
        end

        % Value changed function: SlicesListBox
        function SlicesListBoxValueChanged(app, event)
            hPrgDlg = uiprogressdlg(app.EditDragPropertiesUIFigure, "Indeterminate","on", "Message","Plotting flight envelope data.", "Title","Edit Drag Properties");
            app.populateSliceDataUI(); 
            close(hPrgDlg);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditDragPropertiesUIFigure = uifigure('Visible', 'off');
            app.EditDragPropertiesUIFigure.Name = 'Edit Drag Properties';
            app.EditDragPropertiesUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout = uigridlayout(app.EditDragPropertiesUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Drag Properties';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.FlightEnvelopeOutputPanel = uipanel(app.GridLayout3);
            app.FlightEnvelopeOutputPanel.Title = 'Flight Envelope Output';
            app.GridLayout5 = uigridlayout(app.FlightEnvelopeOutputPanel);
            app.GridLayout4 = uigridlayout(app.GridLayout5);
            app.AngleofAttackLabel = uilabel(app.GridLayout4);
            app.AngleofAttackLabel.Text = 'Angle of Attack';
            app.utUnitLabel = uilabel(app.GridLayout4);
            app.utUnitLabel.Text = 'deg';
            app.AoAText = uieditfield(app.GridLayout4, 'text');
            app.AoAText.ValueChangedFcn = createCallbackFcn(app, @AoATextValueChanged, true);
            app.FlightEnvelopeOutputCSVFilePanel = uipanel(app.GridLayout5);
            app.FlightEnvelopeOutputCSVFilePanel.Title = 'Flight Envelope Output CSV File';
            app.GridLayout6 = uigridlayout(app.FlightEnvelopeOutputCSVFilePanel);
            app.FileSelector = wt.FileSelector(app.GridLayout6);
            app.FileSelector.ValueChangedFcn = createCallbackFcn(app, @FileSelectorValueChanged, true);
            app.WindTunnelDataPanel = uipanel(app.GridLayout5);
            app.WindTunnelDataPanel.Title = 'Wind Tunnel Data';
            app.GridLayout7 = uigridlayout(app.WindTunnelDataPanel);
            app.SliceDispAxes = uiaxes(app.GridLayout7);
            zlabel(app.SliceDispAxes, 'Z')
            app.DataPropertiesPanel = uipanel(app.GridLayout5);
            app.DataPropertiesPanel.Title = 'Data Properties';
            app.GridLayout10 = uigridlayout(app.DataPropertiesPanel);
            app.AoASliceDataTable = uitable(app.GridLayout10);
            app.AoASliceDataTable.ColumnName = {'Min Value'; 'Max Value'; 'Step Size'; 'Num Points'};
            app.AoASliceDataTable.RowName = {'Altitude'; 'Speed'};
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.SlicesListBox = uilistbox(app.GridLayout8);
            app.SlicesListBox.ValueChangedFcn = createCallbackFcn(app, @SlicesListBoxValueChanged, true);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.addAoASliceButton = uibutton(app.GridLayout9, 'push');
            app.addAoASliceButton.ButtonPushedFcn = createCallbackFcn(app, @addAoASliceButtonPushed, true);
            app.addAoASliceButton.Tag = 'addPointToCurveButton';
            app.addAoASliceButton.Icon = 'add.png';
            app.addAoASliceButton.Text = 'Add AoA Slice';
            app.removeAoASliceButton = uibutton(app.GridLayout9, 'push');
            app.removeAoASliceButton.ButtonPushedFcn = createCallbackFcn(app, @removeAoASliceButtonPushed, true);
            app.removeAoASliceButton.Tag = 'removePointFromCurveButton';
            app.removeAoASliceButton.Icon = 'remove.png';
            app.removeAoASliceButton.Text = 'Remove AoA Slice';
            app.WarningsAlertsPanel = uipanel(app.GridLayout2);
            app.WarningsAlertsPanel.Title = 'Warnings & Alerts';
            app.GridLayout11 = uigridlayout(app.WarningsAlertsPanel);
            app.WarningsAlertsTable = uitable(app.GridLayout11);
            app.WarningsAlertsTable.ColumnName = {'Message'};
            app.WarningsAlertsTable.RowName = {};
            app.GridLayout2_2 = uigridlayout(app.GridLayout);
            app.saveCloseButton = uibutton(app.GridLayout2_2, 'push');
            app.saveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveCloseButtonPushed, true);
            app.saveCloseButton.Tag = 'saveCloseButton';
            app.saveCloseButton.Icon = 'save-file.png';
            app.saveCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2_2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.EditDragPropertiesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_Edit3DKerbalWindTunnelDragPropertiesGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditDragPropertiesUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditDragPropertiesUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditDragPropertiesUIFigure)
        end
    end
end