classdef lvd_EditConstDragPropertiesGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditDragPropertiesUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        DragCoefficientCdAPanel     matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        utUnitLabel                 matlab.ui.control.Label
        DragCoeffText               matlab.ui.control.EditField
        text7                       matlab.ui.control.Label
        GridLayout2                 matlab.ui.container.GridLayout
        cancelButton                matlab.ui.control.Button
        saveCloseButton             matlab.ui.control.Button
        text2                       matlab.ui.control.Label
    end

   properties (Access = private)
        output AppDesignerGUIOutput
        lvdData LvdData
        dragCoeffModel ConstantDragCoeffModel
    end
    
    methods (Access = private)
        function populateGUI(app)
            app.DragCoeffText.Value = fullAccNum2Str(app.dragCoeffModel.CdA);
        end
        
        function errMsg = validateInputs(app)
            errMsg = {};

            area = str2double(app.DragCoeffText.Value);
            enteredStr = app.DragCoeffText.Value;
            numberName = 'Drag Coefficient';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(area, numberName, lb, ub, isInt, errMsg, enteredStr);
        end
        
        function saveAndClose(app)
            app.dragCoeffModel.CdA = str2double(app.DragCoeffText.Value);

            app.output.output{1} = true;
            close(app.EditDragPropertiesUIFigure);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dragCoeffModel, lvdData, out)
            centerUIFigure(app.EditDragPropertiesUIFigure);
            applySelectedThemeToApp(app);

            app.lvdData = lvdData;
            app.dragCoeffModel = dragCoeffModel;
            app.output = out;

            app.populateGUI();

            uiwait(app.EditDragPropertiesUIFigure);
        end

        % Value changed function: DragCoeffText
        function DragCoeffTextValueChanged(app, event)
            newInput = app.DragCoeffText.Value;
            newInput = attemptStrEval(newInput);
            app.DragCoeffText.Value = newInput;
        end

        % Button pushed function: saveCloseButton
        function saveCloseButtonPushed(app, event)
            errMsg = validateInputs(app);

            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.EditDragPropertiesUIFigure, errMsg, 'Errors were found while editing drag properties.', "Icon","error");
            end
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            app.output.output = {false};
            close(app.EditDragPropertiesUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditDragPropertiesUIFigure = uifigure('Visible', 'off');
            app.EditDragPropertiesUIFigure.Name = 'Edit Drag Properties';
            app.EditDragPropertiesUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout = uigridlayout(app.EditDragPropertiesUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Drag Properties';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveCloseButtonPushed, true);
            app.saveCloseButton.Tag = 'saveCloseButton';
            app.saveCloseButton.Icon = 'save-file.png';
            app.saveCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.DragCoefficientCdAPanel = uipanel(app.GridLayout);
            app.DragCoefficientCdAPanel.Title = 'Drag Coefficient (Cd*A)';
            app.GridLayout3 = uigridlayout(app.DragCoefficientCdAPanel);
            app.text7 = uilabel(app.GridLayout3);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Drag Coefficient (Cd*A)';
            app.DragCoeffText = uieditfield(app.GridLayout3, 'text');
            app.DragCoeffText.ValueChangedFcn = createCallbackFcn(app, @DragCoeffTextValueChanged, true);
            app.utUnitLabel = uilabel(app.GridLayout3);
            app.utUnitLabel.Interpreter = 'latex';
            app.utUnitLabel.Text = '\(m^2\)';
            % Show the figure after all components are created
            app.EditDragPropertiesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditConstDragPropertiesGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditDragPropertiesUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditDragPropertiesUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditDragPropertiesUIFigure)
        end
    end
end