classdef lvd_EditDragCoefficientModels_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditDragPropertiesUIFigure      matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2_2                   matlab.ui.container.GridLayout
        cancelButton                    matlab.ui.control.Button
        saveCloseButton                 matlab.ui.control.Button
        GridLayout2                     matlab.ui.container.GridLayout
        DragCoefficientModelPanel       matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        GlobalDragCoeffText             matlab.ui.control.EditField
        GlobalDragCoeffModifierLabel    matlab.ui.control.Label
        ModelDescLabel                  matlab.ui.control.Label
        DragCoeffModelCombo             matlab.ui.control.DropDown
        EditDragCoefficientModelButton  matlab.ui.control.Button
        text2                           matlab.ui.control.Label
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput
        lvdData LvdData
        dragCoeffModel DragCoeffModel
    end
    
    methods (Access = private)
        function populateGUI(app)
            app.GlobalDragCoeffText.Value = fullAccNum2Str(app.dragCoeffModel.globalDragMultiplier);

            [listBoxStr, m] = DragCoefficientModelEnum.getListBoxStr();
            app.DragCoeffModelCombo.Items = listBoxStr;
            app.DragCoeffModelCombo.ItemsData = m;
            app.DragCoeffModelCombo.Value = app.dragCoeffModel.dragCoeffObj.enum;

            app.ModelDescLabel.Text = app.dragCoeffModel.dragCoeffObj.enum.desc;
        end

        function saveAndClose(app)
            app.dragCoeffModel.globalDragMultiplier = str2double(app.GlobalDragCoeffText.Value);

            enum = app.DragCoeffModelCombo.Value;
            switch enum
                case DragCoefficientModelEnum.Constant
                    app.dragCoeffModel.dragCoeffObj = app.dragCoeffModel.constDragModel;

                case DragCoefficientModelEnum.OneDim
                    app.dragCoeffModel.dragCoeffObj = app.dragCoeffModel.oneDDragModel;

                case DragCoefficientModelEnum.TwoDimWindTunnel
                    app.dragCoeffModel.dragCoeffObj = app.dragCoeffModel.twoDimWindTunnelDragModel;

                case DragCoefficientModelEnum.ThreeDimWindTunnel
                    app.dragCoeffModel.dragCoeffObj = app.dragCoeffModel.threeDimWindTunnelDragModel;

                case DragCoefficientModelEnum.kOSModel
                    app.dragCoeffModel.dragCoeffObj = app.dragCoeffModel.kosDragModel;

                otherwise
                    error('Unknown drag coefficient model type: %s', enum.name);
            end

            app.output.output{1} = true;
            close(app.EditDragPropertiesUIFigure);
        end
        
        function errMsg = validateInputs(app)
            errMsg = {};

            value = str2double(app.GlobalDragCoeffText.Value);
            enteredStr = app.GlobalDragCoeffText.Value;
            numberName = 'Global Drag Coefficient Modifier';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dragCoeffModel, lvdData, out)
            centerUIFigure(app.EditDragPropertiesUIFigure);
            applySelectedThemeToApp(app);

            app.lvdData = lvdData;
            app.dragCoeffModel = dragCoeffModel;
            app.output = out;

            app.populateGUI();

            uiwait(app.EditDragPropertiesUIFigure);
        end

        % Button pushed function: EditDragCoefficientModelButton
        function EditDragCoefficientModelButtonPushed(app, event)
            enum = app.DragCoeffModelCombo.Value;
            switch enum
                case DragCoefficientModelEnum.Constant
                    app.dragCoeffModel.constDragModel.openEditDialog(app.lvdData);

                case DragCoefficientModelEnum.OneDim
                    app.dragCoeffModel.oneDDragModel.openEditDialog(app.lvdData);

                case DragCoefficientModelEnum.TwoDimWindTunnel
                    app.dragCoeffModel.twoDimWindTunnelDragModel.openEditDialog(app.lvdData);

                case DragCoefficientModelEnum.ThreeDimWindTunnel
                    app.dragCoeffModel.threeDimWindTunnelDragModel.openEditDialog(app.lvdData);

                case DragCoefficientModelEnum.kOSModel
                    app.dragCoeffModel.kosDragModel.openEditDialog(app.lvdData);

                otherwise
                    error('Unknown drag coefficient model type: %s', enum.name);
            end
        end

        % Button pushed function: saveCloseButton
        function saveCloseButtonPushed(app, event)
            errMsg = app.validateInputs();

            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.EditDragPropertiesUIFigure, errMsg, 'Errors were found while editing drag properties.', "Icon","error");
            end
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            app.output.output = {false};
            close(app.EditDragPropertiesUIFigure);
        end

        % Value changed function: DragCoeffModelCombo
        function DragCoeffModelComboValueChanged(app, event)
            value = app.DragCoeffModelCombo.Value;
            app.ModelDescLabel.Text = value.desc;
        end

        % Value changed function: GlobalDragCoeffText
        function GlobalDragCoeffTextValueChanged(app, event)
            newInput = app.GlobalDragCoeffText.Value;
            newInput = attemptStrEval(newInput);
            app.GlobalDragCoeffText.Value = newInput;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditDragPropertiesUIFigure = uifigure('Visible', 'off');
            app.EditDragPropertiesUIFigure.Name = 'Edit Drag Properties';
            app.EditDragPropertiesUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout = uigridlayout(app.EditDragPropertiesUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Drag Properties';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.DragCoefficientModelPanel = uipanel(app.GridLayout2);
            app.DragCoefficientModelPanel.Title = 'Drag Coefficient Model';
            app.GridLayout3 = uigridlayout(app.DragCoefficientModelPanel);
            app.EditDragCoefficientModelButton = uibutton(app.GridLayout3, 'push');
            app.EditDragCoefficientModelButton.ButtonPushedFcn = createCallbackFcn(app, @EditDragCoefficientModelButtonPushed, true);
            app.EditDragCoefficientModelButton.Icon = 'left-chevron_red(1).png';
            app.EditDragCoefficientModelButton.Text = 'Edit Drag Coefficient Model';
            app.DragCoeffModelCombo = uidropdown(app.GridLayout3);
            app.DragCoeffModelCombo.ValueChangedFcn = createCallbackFcn(app, @DragCoeffModelComboValueChanged, true);
            app.ModelDescLabel = uilabel(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.GlobalDragCoeffModifierLabel = uilabel(app.GridLayout4);
            app.GlobalDragCoeffModifierLabel.Text = 'Global Drag Coeff Modifier';
            app.GlobalDragCoeffText = uieditfield(app.GridLayout4, 'text');
            app.GlobalDragCoeffText.ValueChangedFcn = createCallbackFcn(app, @GlobalDragCoeffTextValueChanged, true);
            app.GlobalDragCoeffText.Tooltip = {'The product of dragMultiplier and dragCubeMultiplier from KSP''s "physics.cfg" file.'};
            app.GlobalDragCoeffText.Value = '0.8';
            app.GridLayout2_2 = uigridlayout(app.GridLayout);
            app.saveCloseButton = uibutton(app.GridLayout2_2, 'push');
            app.saveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveCloseButtonPushed, true);
            app.saveCloseButton.Tag = 'saveCloseButton';
            app.saveCloseButton.Icon = 'save-file.png';
            app.saveCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2_2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.EditDragPropertiesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditDragCoefficientModels_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditDragPropertiesUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditDragPropertiesUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditDragPropertiesUIFigure)
        end
    end
end