classdef lvd_EditKosDragPropertiesGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditDragPropertiesUIFigure   matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout3                  matlab.ui.container.GridLayout
        kOSDragCoefficientDataPanel  matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        GridLayout6                  matlab.ui.container.GridLayout
        MachNumberLabel              matlab.ui.control.Label
        MachNumSlider                matlab.ui.control.Slider
        DataAxes                     matlab.ui.control.UIAxes
        kOSScriptOutputCSVFilePanel  matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        FileSelector                 wt.FileSelector
        GridLayout2                  matlab.ui.container.GridLayout
        cancelButton                 matlab.ui.control.Button
        GeneratefromCraftButton      matlab.ui.control.Button
        saveCloseButton              matlab.ui.control.Button
        text2                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput
        lvdData LvdData
        dragCoeffModel KosDragCoeffientModel

        dummyWindTunnelModel KosDragCoeffientModel
    end
    
    methods (Access = private)
        function populateGUI(app)
            app.FileSelector.Value = app.dragCoeffModel.dataFile;
            app.FileSelector.DefaultDirectory = pwd;

            if(not(isempty(app.dragCoeffModel.machNum)) && not(isempty(app.dragCoeffModel.aoa)) && not(isempty(app.dragCoeffModel.sideslip)))
                app.plotData();
            end
        end

        function plotData(app)
            hPrgDlg = uiprogressdlg(app.EditDragPropertiesUIFigure, "Indeterminate","on", "Message","Plotting flight envelope data.", "Title","Edit Drag Properties");

            if(app.FileSelector.ValueIsValidPath)
                dataFile = app.FileSelector.FullPath;
                app.dummyWindTunnelModel = KosDragCoeffientModel(dataFile);

                curMachNum = app.MachNumSlider.Value;

                allMachNum = app.dummyWindTunnelModel.machNum;
                limits = [min(allMachNum), max(allMachNum)];
                app.MachNumSlider.Limits = limits;

                if(curMachNum >= limits(1) && curMachNum <= limits(2))
                    app.MachNumSlider.Value = curMachNum;
                else
                    app.MachNumSlider.Value = limits(1);
                end

                machNum = app.MachNumSlider.Value;

                app.dummyWindTunnelModel.plotDragEnvelope(app.DataAxes, machNum);
            else
                machNum = app.MachNumSlider.Value;
                app.dragCoeffModel.plotDragEnvelope(app.DataAxes, machNum);
            end

            close(hPrgDlg);
        end

        function errMsg = validateInputs(app)
            errMsg = {};

            if(not(app.FileSelector.ValueIsValidPath))
                errMsg{end+1} = 'The selected kOS drag coefficient data file does not exist.';
            else
                try
                    app.dragCoeffModel.dataFile = app.FileSelector.FullPath;
                    app.dragCoeffModel.clearData();
                    app.dragCoeffModel.createGriddedInterpFromFile();
                catch ME
                    errMsg{end+1} = sprintf("Import of the kOS drag coefficient data failed.  Message: \n\n%s", ME.message);
                end
            end
        end
        
        function saveAndClose(app)
            app.dragCoeffModel.dataFile = app.FileSelector.FullPath;
            app.dragCoeffModel.clearData();
            app.dragCoeffModel.createGriddedInterpFromFile();

            app.output.output{1} = true;
            close(app.EditDragPropertiesUIFigure);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dragCoeffModel, lvdData, out)
            centerUIFigure(app.EditDragPropertiesUIFigure);
            applySelectedThemeToApp(app);

            app.lvdData = lvdData;
            app.dragCoeffModel = dragCoeffModel;
            app.output = out;

            app.populateGUI();

            uiwait(app.EditDragPropertiesUIFigure);
        end

        % Button pushed function: saveCloseButton
        function saveCloseButtonPushed(app, event)
            errMsg = validateInputs(app);

            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.EditDragPropertiesUIFigure, errMsg, 'Errors were found while editing drag properties.', "Icon","error");
            end
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            app.output.output = {false};
            close(app.EditDragPropertiesUIFigure);
        end

        % Callback function: FileSelector
        function FileSelectorValueChanged(app, event)
            app.plotData();
        end

        % Value changed function: MachNumSlider
        function MachNumSliderValueChanged(app, event)
            machNum = app.MachNumSlider.Value;

            if(not(isempty(app.dummyWindTunnelModel)))
                app.dummyWindTunnelModel.plotDragEnvelope(app.DataAxes, machNum);
            else
                app.dragCoeffModel.plotDragEnvelope(app.DataAxes, machNum);
            end
        end

        % Button pushed function: GeneratefromCraftButton
        function GeneratefromCraftButtonPushed(app, event)
            %generateFromCraftButtonPushed "Generate from Craft..." button.
            % Picks a .craft file + PartDatabase.cfg, runs the KWT-replay
            % sweep (lvd_importAeroTableFromCraft), and points the file
            % selector at the fresh drag CSV. The drag sibling CSV lands
            % next to it for the drag UI. Save & Close still applies.
            [craftFile, craftPath] = uigetfile({'*.craft', 'KSP Craft Files (*.craft)'}, ...
                'Select KSP Craft File for Drag Table Generation');
            if(isequal(craftFile, 0))
                return;
            end
            [cubeFile, cubePath] = uigetfile({'PartDatabase.cfg', 'PartDatabase.cfg (KSP root)'; ...
                    '*.cfg', 'Config Files (*.cfg)'}, ...
                'Select PartDatabase.cfg (from the KSP install root)', craftPath);
            if(isequal(cubeFile, 0))
                return;
            end
            craftFull = fullfile(craftPath, craftFile);
            cubeFull = fullfile(cubePath, cubeFile);
            try
                result = lvd_importAeroTableFromCraft(app.dragCoeffModel, craftFull, ...
                    struct('cubeDB', cubeFull, 'quiet', true));
                app.FileSelector.Value = result.installedCsv;
                app.plotData();
                if(~isempty(result.warnings))
                    uialert(app.EditDragPropertiesUIFigure, result.warnings, ...
                        'Table generated with warnings.', "Icon", "warning");
                end
            catch ME
                uialert(app.EditDragPropertiesUIFigure, ...
                    sprintf('Drag table generation failed:\n\n%s', ME.message), ...
                    'Generation failed.', "Icon", "error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditDragPropertiesUIFigure = uifigure('Visible', 'off');
            app.EditDragPropertiesUIFigure.Name = 'Edit Drag Properties';
            app.EditDragPropertiesUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout = uigridlayout(app.EditDragPropertiesUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Drag Properties';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveCloseButtonPushed, true);
            app.saveCloseButton.Tag = 'saveCloseButton';
            app.saveCloseButton.Icon = 'save-file.png';
            app.saveCloseButton.Text = 'Save & Close';
            app.GeneratefromCraftButton = uibutton(app.GridLayout2, 'push');
            app.GeneratefromCraftButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratefromCraftButtonPushed, true);
            app.GeneratefromCraftButton.Text = 'Generate from Craft...';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.kOSScriptOutputCSVFilePanel = uipanel(app.GridLayout3);
            app.kOSScriptOutputCSVFilePanel.Title = 'kOS Script Output CSV File';
            app.GridLayout4 = uigridlayout(app.kOSScriptOutputCSVFilePanel);
            app.FileSelector = wt.FileSelector(app.GridLayout4);
            app.FileSelector.ValueChangedFcn = createCallbackFcn(app, @FileSelectorValueChanged, true);
            app.kOSDragCoefficientDataPanel = uipanel(app.GridLayout3);
            app.kOSDragCoefficientDataPanel.Title = 'kOS Drag Coefficient Data';
            app.GridLayout5 = uigridlayout(app.kOSDragCoefficientDataPanel);
            app.DataAxes = uiaxes(app.GridLayout5);
            title(app.DataAxes, 'Title')
            xlabel(app.DataAxes, 'X')
            ylabel(app.DataAxes, 'Y')
            zlabel(app.DataAxes, 'Z')
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.MachNumSlider = uislider(app.GridLayout6);
            app.MachNumSlider.Limits = [0 10];
            app.MachNumSlider.ValueChangedFcn = createCallbackFcn(app, @MachNumSliderValueChanged, true);
            app.MachNumSlider.Tooltip = {'This slider adjusts the Mach number at which the drag coefficient data is plotted.'};
            app.MachNumberLabel = uilabel(app.GridLayout6);
            app.MachNumberLabel.Text = 'Mach Number';
            % Show the figure after all components are created
            app.EditDragPropertiesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditKosDragPropertiesGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditDragPropertiesUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditDragPropertiesUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditDragPropertiesUIFigure)
        end
    end
end