classdef lvd_EditKSPCylinderLiftPropertiesGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditLiftPropertiesUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        CylinderPropertiesPanel     matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        CylinderRadiusText          matlab.ui.control.EditField
        text7_2                     matlab.ui.control.Label
        utUnitLabel_2               matlab.ui.control.Label
        utUnitLabel                 matlab.ui.control.Label
        CylinderHeightText          matlab.ui.control.EditField
        text7                       matlab.ui.control.Label
        GridLayout2                 matlab.ui.container.GridLayout
        cancelButton                matlab.ui.control.Button
        saveCloseButton             matlab.ui.control.Button
        text2                       matlab.ui.control.Label
    end

   properties (Access = private)
        output AppDesignerGUIOutput
        lvdData LvdData
        liftCoeffModel CylindricalLiftModel
    end
    
    methods (Access = private)
        function populateGUI(app)
            app.CylinderHeightText.Value = fullAccNum2Str(app.liftCoeffModel.cylinderLength);
            app.CylinderRadiusText.Value = fullAccNum2Str(app.liftCoeffModel.cylinderRadius);
        end
        
        function errMsg = validateInputs(app)
            errMsg = {};

            length = str2double(app.CylinderHeightText.Value);
            enteredStr = app.CylinderHeightText.Value;
            numberName = 'Cylinder Height';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(length, numberName, lb, ub, isInt, errMsg, enteredStr);

            area = str2double(app.CylinderHeightText.Value);
            enteredStr = app.CylinderHeightText.Value;
            numberName = 'Cylinder Radius';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(area, numberName, lb, ub, isInt, errMsg, enteredStr);
        end
        
        function saveAndClose(app)
            app.liftCoeffModel.cylinderLength = str2double(app.CylinderHeightText.Value);
            app.liftCoeffModel.cylinderRadius = str2double(app.CylinderRadiusText.Value);

            app.output.output{1} = true;
            close(app.EditLiftPropertiesUIFigure);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, liftCoeffModel, lvdData, out)
            centerUIFigure(app.EditLiftPropertiesUIFigure);
            applySelectedThemeToApp(app);

            app.lvdData = lvdData;
            app.liftCoeffModel = liftCoeffModel;
            app.output = out;

            app.populateGUI();

            uiwait(app.EditLiftPropertiesUIFigure);
        end

        % Value changed function: CylinderHeightText
        function CylinderHeightTextValueChanged(app, event)
            newInput = app.CylinderHeightText.Value;
            newInput = attemptStrEval(newInput);
            app.CylinderHeightText.Value = newInput;
        end

        % Button pushed function: saveCloseButton
        function saveCloseButtonPushed(app, event)
            errMsg = validateInputs(app);

            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.EditLiftPropertiesUIFigure, errMsg, 'Errors were found while editing lift properties.', "Icon","error");
            end
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            app.output.output = {false};
            close(app.EditLiftPropertiesUIFigure);
        end

        % Value changed function: CylinderRadiusText
        function CylinderRadiusTextValueChanged(app, event)
            newInput = app.CylinderRadiusText.Value;
            newInput = attemptStrEval(newInput);
            app.CylinderRadiusText.Value = newInput;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditLiftPropertiesUIFigure = uifigure('Visible', 'off');
            app.EditLiftPropertiesUIFigure.Name = 'Edit Lift Properties';
            app.EditLiftPropertiesUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout = uigridlayout(app.EditLiftPropertiesUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Lift Properties';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveCloseButtonPushed, true);
            app.saveCloseButton.Tag = 'saveCloseButton';
            app.saveCloseButton.Icon = 'save-file.png';
            app.saveCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.CylinderPropertiesPanel = uipanel(app.GridLayout);
            app.CylinderPropertiesPanel.Title = 'Cylinder Properties';
            app.GridLayout3 = uigridlayout(app.CylinderPropertiesPanel);
            app.text7 = uilabel(app.GridLayout3);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Cylinder Height';
            app.CylinderHeightText = uieditfield(app.GridLayout3, 'text');
            app.CylinderHeightText.ValueChangedFcn = createCallbackFcn(app, @CylinderHeightTextValueChanged, true);
            app.utUnitLabel = uilabel(app.GridLayout3);
            app.utUnitLabel.Text = 'm';
            app.utUnitLabel_2 = uilabel(app.GridLayout3);
            app.utUnitLabel_2.Text = 'm';
            app.text7_2 = uilabel(app.GridLayout3);
            app.text7_2.Tag = 'text7';
            app.text7_2.Text = 'Cylinder Radius';
            app.CylinderRadiusText = uieditfield(app.GridLayout3, 'text');
            app.CylinderRadiusText.ValueChangedFcn = createCallbackFcn(app, @CylinderRadiusTextValueChanged, true);
            % Show the figure after all components are created
            app.EditLiftPropertiesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditKSPCylinderLiftPropertiesGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditLiftPropertiesUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditLiftPropertiesUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditLiftPropertiesUIFigure)
        end
    end
end