classdef lvd_EditLiftCoefficientModels_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditLiftPropertiesUIFigure      matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2_2                   matlab.ui.container.GridLayout
        cancelButton                    matlab.ui.control.Button
        saveCloseButton                 matlab.ui.control.Button
        GridLayout2                     matlab.ui.container.GridLayout
        LiftCoefficientModelPanel       matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        ModelDescLabel                  matlab.ui.control.Label
        LiftCoeffModelCombo             matlab.ui.control.DropDown
        EditLiftCoefficientModelButton  matlab.ui.control.Button
        text2                           matlab.ui.control.Label
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput
        lvdData LvdData
        liftCoeffModel LiftCoeffModel
    end
    
    methods (Access = private)
        function populateGUI(app)
            [listBoxStr, m] = LiftCoefficientModelEnum.getListBoxStr();
            app.LiftCoeffModelCombo.Items = listBoxStr;
            app.LiftCoeffModelCombo.ItemsData = m;
            app.LiftCoeffModelCombo.Value = app.liftCoeffModel.liftCoeffObj.enum;

            app.ModelDescLabel.Text = app.liftCoeffModel.liftCoeffObj.enum.desc;
        end

        function saveAndClose(app)
            enum = app.LiftCoeffModelCombo.Value;
            switch enum
                case LiftCoefficientModelEnum.KSPCylinder
                    app.liftCoeffModel.liftCoeffObj = app.liftCoeffModel.cylinderModel;

                otherwise
                    error('Unknown lift coefficient model type: %s', enum.name);
            end

            app.output.output{1} = true;
            close(app.EditLiftPropertiesUIFigure);
        end
        
        function errMsg = validateInputs(app)
            errMsg = {};
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, liftCoeffModel, lvdData, out)
            centerUIFigure(app.EditLiftPropertiesUIFigure);
            applySelectedThemeToApp(app);

            app.lvdData = lvdData;
            app.liftCoeffModel = liftCoeffModel;
            app.output = out;

            app.populateGUI();

            uiwait(app.EditLiftPropertiesUIFigure);
        end

        % Button pushed function: EditLiftCoefficientModelButton
        function EditLiftCoefficientModelButtonPushed(app, event)
            enum = app.LiftCoeffModelCombo.Value;
            switch enum
                case LiftCoefficientModelEnum.KSPCylinder
                    app.liftCoeffModel.cylinderModel.openEditDialog(app.lvdData);

                otherwise
                    error('Unknown lift coefficient model type: %s', enum.name);
            end
        end

        % Button pushed function: saveCloseButton
        function saveCloseButtonPushed(app, event)
            errMsg = app.validateInputs();

            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.EditLiftPropertiesUIFigure, errMsg, 'Errors were found while editing lift properties.', "Icon","error");
            end
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            app.output.output = {false};
            close(app.EditLiftPropertiesUIFigure);
        end

        % Value changed function: LiftCoeffModelCombo
        function LiftCoeffModelComboValueChanged(app, event)
            value = app.LiftCoeffModelCombo.Value;
            app.ModelDescLabel.Text = value.desc;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditLiftPropertiesUIFigure = uifigure('Visible', 'off');
            app.EditLiftPropertiesUIFigure.Name = 'Edit Lift Properties';
            app.EditLiftPropertiesUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout = uigridlayout(app.EditLiftPropertiesUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Lift Properties';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.LiftCoefficientModelPanel = uipanel(app.GridLayout2);
            app.LiftCoefficientModelPanel.Title = 'Lift Coefficient Model';
            app.GridLayout3 = uigridlayout(app.LiftCoefficientModelPanel);
            app.EditLiftCoefficientModelButton = uibutton(app.GridLayout3, 'push');
            app.EditLiftCoefficientModelButton.ButtonPushedFcn = createCallbackFcn(app, @EditLiftCoefficientModelButtonPushed, true);
            app.EditLiftCoefficientModelButton.Icon = 'up-chevron_blue(1).png';
            app.EditLiftCoefficientModelButton.Text = 'Edit Lift Coefficient Model';
            app.LiftCoeffModelCombo = uidropdown(app.GridLayout3);
            app.LiftCoeffModelCombo.ValueChangedFcn = createCallbackFcn(app, @LiftCoeffModelComboValueChanged, true);
            app.ModelDescLabel = uilabel(app.GridLayout3);
            app.GridLayout2_2 = uigridlayout(app.GridLayout);
            app.saveCloseButton = uibutton(app.GridLayout2_2, 'push');
            app.saveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveCloseButtonPushed, true);
            app.saveCloseButton.Tag = 'saveCloseButton';
            app.saveCloseButton.Icon = 'save-file.png';
            app.saveCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2_2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.EditLiftPropertiesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditLiftCoefficientModels_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditLiftPropertiesUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditLiftPropertiesUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditLiftPropertiesUIFigure)
        end
    end
end