classdef lvd_EditLiftPropertiesGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditLiftPropertiesGUI  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        uipanel2                   matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        uipanel3                   matlab.ui.container.Panel
        GridLayout6                matlab.ui.container.GridLayout
        text17                     matlab.ui.control.Label
        text16                     matlab.ui.control.Label
        text15                     matlab.ui.control.Label
        liftVectXText              matlab.ui.control.EditField
        liftVectYText              matlab.ui.control.EditField
        liftVectZText              matlab.ui.control.EditField
        text13                     matlab.ui.control.Label
        GridLayout5                matlab.ui.container.GridLayout
        text11                     matlab.ui.control.Label
        liftCoeffText              matlab.ui.control.EditField
        text10                     matlab.ui.control.Label
        GridLayout4                matlab.ui.container.GridLayout
        text9                      matlab.ui.control.Label
        areaText                   matlab.ui.control.EditField
        text8                      matlab.ui.control.Label
        useLiftForceCheckbox       matlab.ui.control.CheckBox
        GridLayout2                matlab.ui.container.GridLayout
        cancelButton               matlab.ui.control.Button
        saveCloseButton            matlab.ui.control.Button
        text7                      matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function populateGUI(app, handles, lvdData)
            initStateModel = lvdData.initStateModel;

            handles.useLiftForceCheckbox.Value = double(initStateModel.aero.useLift);
            handles.areaText.String = fullAccNum2Str(initStateModel.aero.areaLift);
            handles.liftCoeffText.String = fullAccNum2Str(initStateModel.aero.Cl_0);
            handles.liftVectXText.String = fullAccNum2Str(initStateModel.aero.bodyLiftVect(1));
            handles.liftVectYText.String = fullAccNum2Str(initStateModel.aero.bodyLiftVect(2));
            handles.liftVectZText.String = fullAccNum2Str(initStateModel.aero.bodyLiftVect(3));

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            useLiftForceCheckbox_Callback(app, evt);
        end

        function errMsg = validateInputs(app, handles)
            errMsg = {};

            area = str2double(get(handles.areaText,'String'));
            enteredStr = get(handles.areaText,'String');
            numberName = 'Surface Area';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(area, numberName, lb, ub, isInt, errMsg, enteredStr);

            cD = str2double(get(handles.liftCoeffText,'String'));
            enteredStr = get(handles.liftCoeffText,'String');
            numberName = 'Lift Coefficient';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(cD, numberName, lb, ub, isInt, errMsg, enteredStr);

            bodyX = str2double(get(handles.liftVectXText,'String'));
            enteredStr = get(handles.liftVectXText,'String');
            numberName = 'Lift Vector Body X';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(bodyX, numberName, lb, ub, isInt, errMsg, enteredStr);

            bodyY = str2double(get(handles.liftVectYText,'String'));
            enteredStr = get(handles.liftVectYText,'String');
            numberName = 'Lift Vector Body Y';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(bodyY, numberName, lb, ub, isInt, errMsg, enteredStr);

            bodyZ = str2double(get(handles.liftVectZText,'String'));
            enteredStr = get(handles.liftVectZText,'String');
            numberName = 'Lift Vector Body Z';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(bodyZ, numberName, lb, ub, isInt, errMsg, enteredStr);
        end


        function saveAndClose(app, handles)
            lvdData = getappdata(handles.lvd_EditLiftPropertiesGUI,'lvdData');
            initStateModel = lvdData.initStateModel;

            initStateModel.aero.useLift = logical(handles.useLiftForceCheckbox.Value);
            initStateModel.aero.areaLift = str2double(handles.areaText.String);
            initStateModel.aero.Cl_0 = str2double(handles.liftCoeffText.String);
            initStateModel.aero.bodyLiftVect(1) = str2double(handles.liftVectXText.String);
            initStateModel.aero.bodyLiftVect(2) = str2double(handles.liftVectYText.String);
            initStateModel.aero.bodyLiftVect(3) = str2double(handles.liftVectZText.String);

            output2{1} = true;
            output2{2} = initStateModel.aero.useLift;
            output2{3} = initStateModel.aero.areaLift;
            output2{4} = initStateModel.aero.Cl_0;
            output2{5} = initStateModel.aero.bodyLiftVect;

            app.output.output = output2;

            close(handles.lvd_EditLiftPropertiesGUI);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditLiftPropertiesGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditLiftPropertiesGUI (see VARARGIN)

            % Choose default command line output for lvd_EditLiftPropertiesGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            app.output = varargin{2};

            populateGUI(app, handles, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditLiftPropertiesGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditLiftPropertiesGUI);
        end

        % Value changed function: areaText
        function areaText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to areaText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of areaText as text
            %        str2double(get(hObject,'String')) returns contents of areaText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Button pushed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.output.output = {false,false,false,false,false};
            close(handles.lvd_EditLiftPropertiesGUI);
        end

        % Value changed function: liftCoeffText
        function liftCoeffText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to liftCoeffText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of liftCoeffText as text
            %        str2double(get(hObject,'String')) returns contents of liftCoeffText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: liftVectXText
        function liftVectXText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to liftVectXText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of liftVectXText as text
            %        str2double(get(hObject,'String')) returns contents of liftVectXText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: liftVectYText
        function liftVectYText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to liftVectYText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of liftVectYText as text
            %        str2double(get(hObject,'String')) returns contents of liftVectYText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: liftVectZText
        function liftVectZText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to liftVectZText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of liftVectZText as text
            %        str2double(get(hObject,'String')) returns contents of liftVectZText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Window key press function: lvd_EditLiftPropertiesGUI
        function lvd_EditLiftPropertiesGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditLiftPropertiesGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(eventdata.Key)
                case 'return'
                    saveCloseButton_Callback(app, evt);
                case 'enter'
                    saveCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_EditLiftPropertiesGUI);
            end
        end

        % Button pushed function: saveCloseButton
        function saveCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                app.saveAndClose(handles);
            else
                %                 msgbox(errMsg,'Errors were found while editing lift properties.','error');
                uialert(app.lvd_EditLiftPropertiesGUI, errMsg, 'Errors were found while editing lift properties.', "Icon","error");
            end
        end

        % Value changed function: useLiftForceCheckbox
        function useLiftForceCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.useLiftForceCheckbox;

            % hObject    handle to useLiftForceCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of useLiftForceCheckbox
            if(get(hObject,'Value'))
                handles.areaText.Enable = 'on';
                handles.liftCoeffText.Enable = 'on';
                handles.liftVectXText.Enable = 'on';
                handles.liftVectYText.Enable = 'on';
                handles.liftVectZText.Enable = 'on';
            else
                handles.areaText.Enable = 'off';
                handles.liftCoeffText.Enable = 'off';
                handles.liftVectXText.Enable = 'off';
                handles.liftVectYText.Enable = 'off';
                handles.liftVectZText.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditLiftPropertiesGUI = uifigure('Visible', 'off');
            app.lvd_EditLiftPropertiesGUI.Name = 'Edit Lift Properties';
            app.lvd_EditLiftPropertiesGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditLiftPropertiesGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditLiftPropertiesGUI_WindowKeyPressFcn, true);
            app.lvd_EditLiftPropertiesGUI.Tag = 'lvd_EditLiftPropertiesGUI';
            app.GridLayout = uigridlayout(app.lvd_EditLiftPropertiesGUI);
            app.text7 = uilabel(app.GridLayout);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Edit Lift Properties';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveCloseButton_Callback, true);
            app.saveCloseButton.Tag = 'saveCloseButton';
            app.saveCloseButton.Icon = 'save-file.png';
            app.saveCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            app.uipanel2 = uipanel(app.GridLayout);
            app.uipanel2.Title = 'Drag Properties';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout3 = uigridlayout(app.uipanel2);
            app.useLiftForceCheckbox = uicheckbox(app.GridLayout3);
            app.useLiftForceCheckbox.ValueChangedFcn = createCallbackFcn(app, @useLiftForceCheckbox_Callback, true);
            app.useLiftForceCheckbox.Tag = 'useLiftForceCheckbox';
            app.useLiftForceCheckbox.Tooltip = 'If checked, LVD will compute a lift force generated by the vehicle.  Useful for modeling aircraft that drop air-launched rockets.';
            app.useLiftForceCheckbox.Text = 'Use Lift Force';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.text8 = uilabel(app.GridLayout4);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Surface Area';
            app.areaText = uieditfield(app.GridLayout4, 'text');
            app.areaText.ValueChangedFcn = createCallbackFcn(app, @areaText_Callback, true);
            app.areaText.Tag = 'areaText';
            app.areaText.Value = '0.0';
            app.text9 = uilabel(app.GridLayout4);
            app.text9.Tag = 'text9';
            app.text9.Text = 'm^2';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.text10 = uilabel(app.GridLayout5);
            app.text10.Tag = 'text10';
            app.text10.Tooltip = 'The lift coefficient at zero angle of attack.';
            app.text10.Text = 'Lift Coefficient';
            app.liftCoeffText = uieditfield(app.GridLayout5, 'text');
            app.liftCoeffText.ValueChangedFcn = createCallbackFcn(app, @liftCoeffText_Callback, true);
            app.liftCoeffText.Tag = 'liftCoeffText';
            app.liftCoeffText.Tooltip = 'The lift coefficient at zero angle of attack.';
            app.liftCoeffText.Value = '0.0';
            app.text11 = uilabel(app.GridLayout5);
            app.text11.Tag = 'text11';
            app.text11.Tooltip = 'The lift coefficient at zero angle of attack.';
            app.text11.Text = '';
            app.uipanel3 = uipanel(app.GridLayout3);
            app.uipanel3.Title = 'Body-Frame Lift Vector';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout6 = uigridlayout(app.uipanel3);
            app.text13 = uilabel(app.GridLayout6);
            app.text13.Tag = 'text13';
            app.text13.Tooltip = 'The lift coefficient at zero angle of attack.';
            app.text13.Text = '';
            app.liftVectZText = uieditfield(app.GridLayout6, 'text');
            app.liftVectZText.ValueChangedFcn = createCallbackFcn(app, @liftVectZText_Callback, true);
            app.liftVectZText.Tag = 'liftVectZText';
            app.liftVectZText.Value = '0.0';
            app.liftVectYText = uieditfield(app.GridLayout6, 'text');
            app.liftVectYText.ValueChangedFcn = createCallbackFcn(app, @liftVectYText_Callback, true);
            app.liftVectYText.Tag = 'liftVectYText';
            app.liftVectYText.Value = '0.0';
            app.liftVectXText = uieditfield(app.GridLayout6, 'text');
            app.liftVectXText.ValueChangedFcn = createCallbackFcn(app, @liftVectXText_Callback, true);
            app.liftVectXText.Tag = 'liftVectXText';
            app.liftVectXText.Value = '0.0';
            app.text15 = uilabel(app.GridLayout6);
            app.text15.Tag = 'text15';
            app.text15.Text = 'X';
            app.text16 = uilabel(app.GridLayout6);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Y';
            app.text17 = uilabel(app.GridLayout6);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Z';
            % Show the figure after all components are created
            app.lvd_EditLiftPropertiesGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditLiftPropertiesGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditLiftPropertiesGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditLiftPropertiesGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditLiftPropertiesGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditLiftPropertiesGUI)
        end
    end
end