classdef lvd_EditDisplayFrameAndElementSet_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditOrbitDisplayUIFigure  matlab.ui.Figure
        GridLayout6               matlab.ui.container.GridLayout
        GridLayout7               matlab.ui.container.GridLayout
        CancelButton              matlab.ui.control.Button
        SaveCloseButton           matlab.ui.control.Button
        text2                     matlab.ui.control.Label
        uipanel3                  matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        UseCentralBodyInertialFrameCheckBox  matlab.ui.control.CheckBox
        GridLayout5               matlab.ui.container.GridLayout
        refFrameTypeCombo         matlab.ui.control.DropDown
        setFrameOptionsButton     matlab.ui.control.Button
        elementSetCombo           matlab.ui.control.DropDown
        text24                    matlab.ui.control.Label
        text17                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        lvdData LvdData
        refFrame AbstractReferenceFrame 
        out(1,1) AppDesignerGUIOutput
    end
    
    methods (Access = private)
        function refFrameTypeChanged(app)
            enum = app.refFrameTypeCombo.Value;

            if(isempty(app.refFrame))
                app.refFrame = LvdData.getDefaultInitialBodyInfo(app.lvdData.celBodyData).getBodyCenteredInertialFrame();
            end

            switch enum
                case ReferenceFrameEnum.BodyCenteredInertial
                    bodyInfo = app.refFrame.getOriginBody();

                    app.refFrame = bodyInfo.getBodyCenteredInertialFrame();

                case ReferenceFrameEnum.BodyFixedRotating
                    bodyInfo = app.refFrame.getOriginBody();

                    app.refFrame = bodyInfo.getBodyFixedFrame();

                case ReferenceFrameEnum.TwoBodyRotating
                    bodyInfo = app.refFrame.getOriginBody();

                    if(not(isempty(bodyInfo.childrenBodyInfo)))
                        primaryBody = bodyInfo;
                        secondaryBody = bodyInfo.childrenBodyInfo(1);
                    else
                        primaryBody = bodyInfo.getParBodyInfo();
                        secondaryBody = bodyInfo;
                    end

                    originPt = TwoBodyRotatingFrameOriginEnum.Primary;

                    app.refFrame = TwoBodyRotatingFrame(primaryBody, secondaryBody, originPt, app.lvdData.celBodyData);

                case ReferenceFrameEnum.UserDefined
                        numFrames = app.lvdData.geometry.refFrames.getNumRefFrames();
                        if(numFrames >= 1)
                            geometricFrame = AbstractGeometricRefFrame.empty(1,0);
                            for(i=1:numFrames) %#ok<*NO4LP> 
                                frame = app.lvdData.geometry.refFrames.getRefFrameAtInd(i);
                                if(frame.isVehDependent() == false)
                                    geometricFrame = frame;
                                    break;
                                end
                            end

                            if(not(isempty(geometricFrame)))
                                app.refFrame = UserDefinedGeometricFrame(geometricFrame, app.lvdData);
                            else
                                bodyInfo = app.refFrame.getOriginBody();
                                app.refFrame = bodyInfo.getBodyCenteredInertialFrame();

                                warndlg('There are no geometric frames available which are not dependent on vehicle properties.  A body-centered inertial frame will be selected instead.');
                            end
                        else
                            bodyInfo = app.refFrame.getOriginBody();
                            app.refFrame = bodyInfo.getBodyCenteredInertialFrame();

                            warndlg('There are no geometric frames available which are not dependent on vehicle properties.  A body-centered inertial frame will be selected instead.');
                        end

                otherwise
                    error('Unknown reference frame type: %s', string(refFrameEnum));
            end

            app.setFrameOptionsButton.Tooltip = sprintf('Current Frame: %s', app.refFrame.getNameStr());
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, lvdData, curElementSet, curReferenceFrame, out)
            arguments
                app(1,1) lvd_EditDisplayFrameAndElementSet_App
                lvdData(1,1) LvdData
                curElementSet(1,1) ElementSetEnum
                curReferenceFrame AbstractReferenceFrame
                out(1,1) AppDesignerGUIOutput
            end

            centerUIFigure(app.EditOrbitDisplayUIFigure);
            applySelectedThemeToApp(app);

            app.lvdData = lvdData;
            app.out = out;
            
            [listboxStr, m] = ElementSetEnum.getListBoxStr();
            app.elementSetCombo.Items = listboxStr;
            app.elementSetCombo.ItemsData = m;
            app.elementSetCombo.Value = curElementSet;

            [listBoxStr, m] = ReferenceFrameEnum.getListBoxStr();
            app.refFrameTypeCombo.Items = listBoxStr;
            app.refFrameTypeCombo.ItemsData = m;

            if(isempty(curReferenceFrame))
                app.UseCentralBodyInertialFrameCheckBox.Value = 1;
                app.refFrameTypeCombo.Enable = "off";
                app.setFrameOptionsButton.Enable = "off";
            else
                app.refFrameTypeCombo.Value = curReferenceFrame.typeEnum;
                app.setFrameOptionsButton.Tooltip = sprintf('Current Frame: %s', curReferenceFrame.getNameStr());
            end

            app.refFrame = curReferenceFrame;

            uiwait(app.EditOrbitDisplayUIFigure);
        end

        % Value changed function: refFrameTypeCombo
        function refFrameTypeComboValueChanged(app, event)
            app.refFrameTypeChanged();
        end

        % Button pushed function: setFrameOptionsButton
        function setFrameOptionsButtonPushed(app, event)
            app.refFrame = app.refFrame.editFrameDialogUI(EditReferenceFrameContextEnum.ForState);
        end

        % Button pushed function: SaveCloseButton
        function SaveCloseButtonPushed(app, event)
            if(app.UseCentralBodyInertialFrameCheckBox.Value)
                app.refFrame = AbstractReferenceFrame.empty(1,0);
            end

            app.out.output = {app.refFrame, app.elementSetCombo.Value};

            close(app.EditOrbitDisplayUIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.out.output = {false, false};

            close(app.EditOrbitDisplayUIFigure);
        end

        % Value changed function: UseCentralBodyInertialFrameCheckBox
        function UseCentralBodyInertialFrameCheckBoxValueChanged(app, event)
            value = app.UseCentralBodyInertialFrameCheckBox.Value;
            if(value)
                app.refFrameTypeCombo.Enable = "off";
                app.setFrameOptionsButton.Enable = "off";
                app.setFrameOptionsButton.Tooltip = '';
            else
                app.refFrameTypeCombo.Enable = "on";
                app.setFrameOptionsButton.Enable = "on";
                app.refFrameTypeChanged();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditOrbitDisplayUIFigure = uifigure('Visible', 'off');
            app.EditOrbitDisplayUIFigure.Name = 'Edit Orbit Display';
            app.EditOrbitDisplayUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout6 = uigridlayout(app.EditOrbitDisplayUIFigure);
            app.uipanel3 = uipanel(app.GridLayout6);
            app.uipanel3.Title = 'Display Parameters';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout2 = uigridlayout(app.uipanel3);
            app.text17 = uilabel(app.GridLayout2);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Element Set';
            app.text24 = uilabel(app.GridLayout2);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Frame Type';
            app.elementSetCombo = uidropdown(app.GridLayout2);
            app.elementSetCombo.Items = {'Pop-up Menu'};
            app.elementSetCombo.Tag = 'elementSetCombo';
            app.elementSetCombo.Tooltip = 'The elements used to define the state (position and velocity) of the vehicle.';
            app.elementSetCombo.Value = 'Pop-up Menu';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.setFrameOptionsButton = uibutton(app.GridLayout5, 'push');
            app.setFrameOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @setFrameOptionsButtonPushed, true);
            app.setFrameOptionsButton.Tag = 'setFrameOptionsButton';
            app.setFrameOptionsButton.Text = 'Set Frame Options';
            app.refFrameTypeCombo = uidropdown(app.GridLayout5);
            app.refFrameTypeCombo.Items = {'Pop-up Menu'};
            app.refFrameTypeCombo.ValueChangedFcn = createCallbackFcn(app, @refFrameTypeComboValueChanged, true);
            app.refFrameTypeCombo.Tag = 'refFrameTypeCombo';
            app.refFrameTypeCombo.Tooltip = 'The type of reference frame used with the selected element set, relative to the selected central body.  Generally, use Keplerian elements with an inertial frame and geographic elements with a body-fixed frame.';
            app.refFrameTypeCombo.Value = 'Pop-up Menu';
            app.UseCentralBodyInertialFrameCheckBox = uicheckbox(app.GridLayout2);
            app.UseCentralBodyInertialFrameCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseCentralBodyInertialFrameCheckBoxValueChanged, true);
            app.UseCentralBodyInertialFrameCheckBox.Tooltip = {'When checked, the reference frame used will be the inertial frame centered at the celestial body the vehicle is currently orbiting.  This is the prior default behavior.'};
            app.UseCentralBodyInertialFrameCheckBox.Text = 'Use Central Body Inertial Frame';
            app.text2 = uilabel(app.GridLayout6);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Orbit Display';
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.SaveCloseButton = uibutton(app.GridLayout7, 'push');
            app.SaveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCloseButtonPushed, true);
            app.SaveCloseButton.Icon = 'save-file.png';
            app.SaveCloseButton.Text = 'Save & Close';
            app.CancelButton = uibutton(app.GridLayout7, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Icon = 'cancel.png';
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.EditOrbitDisplayUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditDisplayFrameAndElementSet_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditOrbitDisplayUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditOrbitDisplayUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditOrbitDisplayUIFigure)
        end
    end
end