classdef lvd_EditLvAndStagesStatesGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditLvAndStagesStatesGUI   matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        GridLayout6                    matlab.ui.container.GridLayout
        uipanel7                       matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        uipanel10                      matlab.ui.container.Panel
        GridLayout14                   matlab.ui.container.GridLayout
        pwrStorageActiveCheckbox       matlab.ui.control.CheckBox
        powerStorageListbox            matlab.ui.control.ListBox
        uipanel9                       matlab.ui.container.Panel
        GridLayout13                   matlab.ui.container.GridLayout
        pwrSinkActiveCheckbox          matlab.ui.control.CheckBox
        powerSinkListbox               matlab.ui.control.ListBox
        uipanel8                       matlab.ui.container.Panel
        GridLayout12                   matlab.ui.container.GridLayout
        pwrSrcActiveCheckbox           matlab.ui.control.CheckBox
        powerSrcListbox                matlab.ui.control.ListBox
        uipanel6                       matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        uipanel5                       matlab.ui.container.Panel
        GridLayout11                   matlab.ui.container.GridLayout
        t2tConnCheckbox                matlab.ui.control.CheckBox
        t2tConnListbox                 matlab.ui.control.ListBox
        uipanel4                       matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        connCheckbox                   matlab.ui.control.CheckBox
        connListbox                    matlab.ui.control.ListBox
        uipanel3                       matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        engineActiveCheckbox           matlab.ui.control.CheckBox
        engineListbox                  matlab.ui.control.ListBox
        uipanel2                       matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        stageActiveCheckbox            matlab.ui.control.CheckBox
        stageListbox                   matlab.ui.control.ListBox
        holdDownClampsEnabledCheckbox  matlab.ui.control.CheckBox
        GridLayout2                    matlab.ui.container.GridLayout
        closeButton                    matlab.ui.control.Button
        text2                          matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function state = getSelectedConnState(app, handles)
            lvdData = getappdata(handles.lvd_EditLvAndStagesStatesGUI, 'lvdData');
            lv = lvdData.launchVehicle;
            initStateModel = lvdData.initStateModel;
            lvState = initStateModel.lvState;

            selConn = get(handles.connListbox,'Value');
            conn = lv.getEngineToTankForInd(selConn);

            e2TConnStates = lvState.e2TConns;
            connInd = find([e2TConnStates.conn] == conn,1,'first');

            state = e2TConnStates(connInd);
        end

        function state = getSelectedEngineState(app, handles)
            lvdData = getappdata(handles.lvd_EditLvAndStagesStatesGUI, 'lvdData');
            lv = lvdData.launchVehicle;
            initStateModel = lvdData.initStateModel;
            stageStates = initStateModel.stageStates;

            engineStates = LaunchVehicleEngineState.empty(1,0);
            for(i=1:length(stageStates)) %#ok<*NO4LP>
                engineStates = horzcat(engineStates, stageStates(i).engineStates); %#ok<AGROW>
            end

            selEngine = get(handles.engineListbox,'Value');
            engine = lv.getEngineForInd(selEngine);

            engineInd = find([engineStates.engine] == engine,1,'first');
            state = engineStates(engineInd);
        end

        function state = getSelectedPwrSinkState(app, handles)
            lvdData = getappdata(handles.lvd_EditLvAndStagesStatesGUI, 'lvdData');
            lv = lvdData.launchVehicle;
            initStateModel = lvdData.initStateModel;
            stageStates = initStateModel.stageStates;

            pwrSinkStates = AbstractLaunchVehicleElectricalPowerSnkState.empty(1,0);
            for(i=1:length(stageStates)) %#ok<*NO4LP>
                pwrSinkStates = horzcat(pwrSinkStates, stageStates(i).powerSinkStates); %#ok<AGROW>
            end

            selPwrSink = get(handles.powerSinkListbox,'Value');
            pwrSink = lv.getPowerSinkForInd(selPwrSink);

            pwrSinks = AbstractLaunchVehicleElectricalPowerSrcSnk.empty(1,0);
            for(i=1:length(pwrSinkStates))
                pwrSinks = horzcat(pwrSinks, pwrSinkStates(i).getEpsSinkComponent()); %#ok<AGROW>
            end

            pwrSinkInd = find(pwrSinks == pwrSink,1,'first');
            state = pwrSinkStates(pwrSinkInd);
        end

        function state = getSelectedPwrSrcState(app, handles)
            lvdData = getappdata(handles.lvd_EditLvAndStagesStatesGUI, 'lvdData');
            lv = lvdData.launchVehicle;
            initStateModel = lvdData.initStateModel;
            stageStates = initStateModel.stageStates;

            pwrSrcStates = AbstractLaunchVehicleElectricalPowerSrcState.empty(1,0);
            for(i=1:length(stageStates)) %#ok<*NO4LP>
                pwrSrcStates = horzcat(pwrSrcStates, stageStates(i).powerSrcStates); %#ok<AGROW>
            end

            selPwrSrc = get(handles.powerSrcListbox,'Value');
            pwrSrc = lv.getPowerSrcForInd(selPwrSrc);

            pwrSrcs = AbstractLaunchVehicleElectricalPowerSrcSnk.empty(1,0);
            for(i=1:length(pwrSrcStates))
                pwrSrcs = horzcat(pwrSrcs, pwrSrcStates(i).getEpsSrcComponent()); %#ok<AGROW>
            end

            pwrSrcInd = find(pwrSrcs == pwrSrc,1,'first');
            state = pwrSrcStates(pwrSrcInd);
        end

        function state = getSelectedPwrStorageState(app, handles)
            lvdData = getappdata(handles.lvd_EditLvAndStagesStatesGUI, 'lvdData');
            lv = lvdData.launchVehicle;
            initStateModel = lvdData.initStateModel;
            stageStates = initStateModel.stageStates;

            pwrStorageStates = AbstractLaunchVehicleEpsStorageState.empty(1,0);
            for(i=1:length(stageStates)) %#ok<*NO4LP>
                pwrStorageStates = horzcat(pwrStorageStates, stageStates(i).powerStorageStates); %#ok<AGROW>
            end

            selPwrStorage = get(handles.powerStorageListbox,'Value');
            pwrStorage = lv.getPowerStorageForInd(selPwrStorage);

            pwrStorages = AbstractLaunchVehicleElectricalPowerStorage.empty(1,0);
            for(i=1:length(pwrStorageStates))
                pwrStorages = horzcat(pwrStorages, pwrStorageStates(i).getEpsStorageComponent()); %#ok<AGROW>
            end

            pwrStorageInd = find(pwrStorages == pwrStorage,1,'first');
            state = pwrStorageStates(pwrStorageInd);
        end

        function state = getSelectedStageState(app, handles)
            lvdData = getappdata(handles.lvd_EditLvAndStagesStatesGUI, 'lvdData');
            lv = lvdData.launchVehicle;
            initStateModel = lvdData.initStateModel;
            stageStates = initStateModel.stageStates;

            selStage = get(handles.stageListbox,'Value');
            stage = lv.getStageForInd(selStage);

            stageInd = find([stageStates.stage] == stage,1,'first');
            state = stageStates(stageInd);
        end

        function state = getSelectedT2TConnState(app, handles)
            lvdData = getappdata(handles.lvd_EditLvAndStagesStatesGUI, 'lvdData');
            lv = lvdData.launchVehicle;
            initStateModel = lvdData.initStateModel;
            lvState = initStateModel.lvState;

            t2TConnStates = lvState.t2TConns;

            selConn = get(handles.t2tConnListbox,'Value');
            conn = lv.getTankToTankForInd(selConn);

            connInd = find([t2TConnStates.conn] == conn,1,'first');

            state = t2TConnStates(connInd);
        end

        function populateGUI(app, handles, lvdData)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            lv = lvdData.launchVehicle;
            initStateModel = lvdData.initStateModel;
            lvState = initStateModel.lvState;

            handles.stageListbox.String = lv.getStagesListBoxStr();
            stageListbox_Callback(app, evt);

            %Propulsion
            handles.engineListbox.String = lv.getEnginesListBoxStr();
            engineListbox_Callback(app, evt);

            handles.connListbox.String = lv.getEngineToTankConnectionsListBoxStr();
            e2TConnStates = lvState.e2TConns;
            if(not(isempty(e2TConnStates)))
                connListbox_Callback(app, evt);
            else
                handles.connListbox.Enable = 'off';
                handles.connCheckbox.Enable = 'off';
            end

            handles.t2tConnListbox.String = lv.getTankToTankConnectionsListBoxStr();
            t2TConnStates = lvState.t2TConns;
            if(not(isempty(t2TConnStates)))
                t2tConnListbox_Callback(app, evt);
            else
                handles.t2tConnListbox.Enable = 'off';
                handles.t2tConnCheckbox.Enable = 'off';
            end

            %Electrical Power Systems
            [handles.powerSrcListbox.String, pwrSrcs] = lv.getPowerSrcsListBoxStr();
            if(not(isempty(pwrSrcs)))
                powerSrcListbox_Callback(app, evt);
            else
                handles.powerSrcListbox.Enable = 'off';
                handles.pwrSrcActiveCheckbox.Enable = 'off';
            end

            [handles.powerSinkListbox.String, pwrSinks] = lv.getPowerSinksListBoxStr();
            if(not(isempty(pwrSinks)))
                powerSinkListbox_Callback(app, evt);
            else
                handles.powerSinkListbox.Enable = 'off';
                handles.pwrSinkActiveCheckbox.Enable = 'off';
            end

            [handles.powerStorageListbox.String, pwrStorage] = lv.getPowerStoragesListBoxStr();
            if(not(isempty(pwrStorage)))
                powerStorageListbox_Callback(app, evt);
            else
                handles.powerStorageListbox.Enable = 'off';
                handles.pwrStorageActiveCheckbox.Enable = 'off';
            end

            %Hold Down Clamp
            initStateModel = lvdData.initStateModel;
            handles.holdDownClampsEnabledCheckbox.Value = double(initStateModel.lvState.holdDownEnabled);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditLvAndStagesStatesGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditLvAndStagesStatesGUI (see VARARGIN)

            % Choose default command line output for lvd_EditLvAndStagesStatesGUI
            handles.output = hObject;

            centerUIFigure(hObject);
            applySelectedThemeToApp(app);

            lvdData = varargin{1};
            setappdata(hObject, 'lvdData', lvdData);

            app.output = varargin{2};

            populateGUI(app, handles, lvdData)

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditLvAndStagesStatesGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditLvAndStagesStatesGUI);
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to closeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.output.output = {true};
            close(handles.lvd_EditLvAndStagesStatesGUI);
        end

        % Value changed function: connCheckbox
        function connCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.connCheckbox;

            % hObject    handle to connCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of connCheckbox
            state = getSelectedConnState(app, handles);
            state.active = logical(get(hObject,'Value'));
        end

        % Value changed function: connListbox
        function connListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to connListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns connListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from connListbox
            state = getSelectedConnState(app, handles);
            handles.connCheckbox.Value = double(state.active);

            if(strcmpi(get(handles.lvd_EditLvAndStagesStatesGUI,'SelectionType'),'open'))
                handles.connCheckbox.Value = double(not(logical(handles.connCheckbox.Value)));
            end

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            connCheckbox_Callback(app, evt);
        end

        % Value changed function: engineActiveCheckbox
        function engineActiveCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.engineActiveCheckbox;

            % hObject    handle to engineActiveCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of engineActiveCheckbox
            state = getSelectedEngineState(app, handles);
            state.active = logical(get(hObject,'Value'));
        end

        % Value changed function: engineListbox
        function engineListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to engineListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns engineListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from engineListbox
            state = getSelectedEngineState(app, handles);
            handles.engineActiveCheckbox.Value = double(state.active);

            if(strcmpi(get(handles.lvd_EditLvAndStagesStatesGUI,'SelectionType'),'open'))
                handles.engineActiveCheckbox.Value = double(not(logical(handles.engineActiveCheckbox.Value)));
            end
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            engineActiveCheckbox_Callback(app, evt);
        end

        % Value changed function: holdDownClampsEnabledCheckbox
        function holdDownClampsEnabledCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to holdDownClampsEnabledCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of holdDownClampsEnabledCheckbox
            lvdData = getappdata(handles.lvd_EditLvAndStagesStatesGUI, 'lvdData');
            initStateModel = lvdData.initStateModel;
            initStateModel.lvState.holdDownEnabled = get(hObject,'Value');
        end

        % Window key press function: lvd_EditLvAndStagesStatesGUI
        function lvd_EditLvAndStagesStatesGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditLvAndStagesStatesGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            switch(eventdata.Key)
                case 'return'
                    uiresume(handles.lvd_EditLvAndStagesStatesGUI);
                case 'enter'
                    uiresume(handles.lvd_EditLvAndStagesStatesGUI);
                case 'escape'
                    uiresume(handles.lvd_EditLvAndStagesStatesGUI);
            end
        end

        % Value changed function: powerSinkListbox
        function powerSinkListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to powerSinkListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns powerSinkListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from powerSinkListbox
            state = getSelectedPwrSinkState(app, handles);
            handles.pwrSinkActiveCheckbox.Value = double(state.getActiveState());

            if(strcmpi(get(handles.lvd_EditLvAndStagesStatesGUI,'SelectionType'),'open'))
                handles.pwrSinkActiveCheckbox.Value = double(not(logical(handles.pwrSinkActiveCheckbox.Value)));
            end

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            pwrSinkActiveCheckbox_Callback(app, evt);
        end

        % Value changed function: powerSrcListbox
        function powerSrcListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to powerSrcListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns powerSrcListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from powerSrcListbox
            state = getSelectedPwrSrcState(app, handles);
            handles.pwrSrcActiveCheckbox.Value = double(state.getActiveState());

            if(strcmpi(get(handles.lvd_EditLvAndStagesStatesGUI,'SelectionType'),'open'))
                handles.pwrSrcActiveCheckbox.Value = double(not(logical(handles.pwrSrcActiveCheckbox.Value)));
            end

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            pwrSrcActiveCheckbox_Callback(app, evt);
        end

        % Value changed function: powerStorageListbox
        function powerStorageListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to powerStorageListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns powerStorageListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from powerStorageListbox
            state = getSelectedPwrStorageState(app, handles);
            handles.pwrStorageActiveCheckbox.Value = double(state.getActiveState());

            if(strcmpi(get(handles.lvd_EditLvAndStagesStatesGUI,'SelectionType'),'open'))
                handles.pwrStorageActiveCheckbox.Value = double(not(logical(handles.pwrStorageActiveCheckbox.Value)));
            end

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            pwrStorageActiveCheckbox_Callback(app, evt);
        end

        % Value changed function: pwrSinkActiveCheckbox
        function pwrSinkActiveCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.pwrSinkActiveCheckbox;

            % hObject    handle to pwrSinkActiveCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of pwrSinkActiveCheckbox
            state = getSelectedPwrSinkState(app, handles);
            state.setActiveState(logical(get(hObject,'Value')));
        end

        % Value changed function: pwrSrcActiveCheckbox
        function pwrSrcActiveCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.pwrSrcActiveCheckbox;

            % hObject    handle to pwrSrcActiveCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of pwrSrcActiveCheckbox
            state = getSelectedPwrSrcState(app, handles);
            state.setActiveState(logical(get(hObject,'Value')));
        end

        % Value changed function: pwrStorageActiveCheckbox
        function pwrStorageActiveCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.pwrStorageActiveCheckbox;

            % hObject    handle to pwrStorageActiveCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of pwrStorageActiveCheckbox
            state = getSelectedPwrStorageState(app, handles);
            state.setActiveState(logical(get(hObject,'Value')));
        end

        % Value changed function: stageActiveCheckbox
        function stageActiveCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.stageActiveCheckbox;

            % hObject    handle to stageActiveCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of stageActiveCheckbox
            state = getSelectedStageState(app, handles);
            state.active = logical(get(hObject,'Value'));
        end

        % Value changed function: stageListbox
        function stageListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            %             hObject = handles.stageListbox;

            % hObject    handle to stageListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns stageListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from stageListbox
            state = getSelectedStageState(app, handles);
            handles.stageActiveCheckbox.Value = double(state.active);

            if(strcmpi(get(handles.lvd_EditLvAndStagesStatesGUI,'SelectionType'),'open'))
                handles.stageActiveCheckbox.Value = double(not(logical(handles.stageActiveCheckbox.Value)));
            end

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            stageActiveCheckbox_Callback(app, evt);
        end

        % Value changed function: t2tConnCheckbox
        function t2tConnCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.t2tConnCheckbox;

            % hObject    handle to t2tConnCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of t2tConnCheckbox
            state = getSelectedT2TConnState(app, handles);
            state.active = logical(get(hObject,'Value'));
        end

        % Value changed function: t2tConnListbox
        function t2tConnListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to t2tConnListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns t2tConnListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from t2tConnListbox
            state = getSelectedT2TConnState(app, handles);
            handles.t2tConnCheckbox.Value = double(state.active);

            if(strcmpi(get(handles.lvd_EditLvAndStagesStatesGUI,'SelectionType'),'open'))
                handles.t2tConnCheckbox.Value = double(not(logical(handles.t2tConnCheckbox.Value)));
            end

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            t2tConnCheckbox_Callback(app, evt);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditLvAndStagesStatesGUI = uifigure('Visible', 'off');
            app.lvd_EditLvAndStagesStatesGUI.Name = 'Edit Launch Vehicle & Stage States';
            app.lvd_EditLvAndStagesStatesGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditLvAndStagesStatesGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditLvAndStagesStatesGUI_WindowKeyPressFcn, true);
            app.lvd_EditLvAndStagesStatesGUI.Tag = 'lvd_EditLvAndStagesStatesGUI';
            app.GridLayout = uigridlayout(app.lvd_EditLvAndStagesStatesGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Initial Launch Vehicle & Stage States';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.closeButton = uibutton(app.GridLayout2, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Icon = 'save-file.png';
            app.closeButton.Text = 'Save & Close';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.Panel);
            app.holdDownClampsEnabledCheckbox = uicheckbox(app.GridLayout5);
            app.holdDownClampsEnabledCheckbox.ValueChangedFcn = createCallbackFcn(app, @holdDownClampsEnabledCheckbox_Callback, true);
            app.holdDownClampsEnabledCheckbox.Tag = 'holdDownClampsEnabledCheckbox';
            app.holdDownClampsEnabledCheckbox.Tooltip = 'When checked, the launch vehicle will be fixed to the latitude, longitude, and altitude it is currently at.  Use an event action to enable or disable.  Note that this completely changes the vehicle equations of motion, so use with caution.';
            app.holdDownClampsEnabledCheckbox.Text = 'Hold Down Clamps are Enabled?';
            app.uipanel2 = uipanel(app.GridLayout5);
            app.uipanel2.Title = 'Stage States';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4 = uigridlayout(app.uipanel2);
            app.stageListbox = uilistbox(app.GridLayout4);
            app.stageListbox.Items = {'Listbox'};
            app.stageListbox.ValueChangedFcn = createCallbackFcn(app, @stageListbox_Callback, true);
            app.stageListbox.Tag = 'stageListbox';
            app.stageListbox.Value = 'Listbox';
            app.stageActiveCheckbox = uicheckbox(app.GridLayout4);
            app.stageActiveCheckbox.ValueChangedFcn = createCallbackFcn(app, @stageActiveCheckbox_Callback, true);
            app.stageActiveCheckbox.Tag = 'stageActiveCheckbox';
            app.stageActiveCheckbox.Text = 'Stage is Active?';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.uipanel6 = uipanel(app.GridLayout6);
            app.uipanel6.Title = 'Propulsion States';
            app.uipanel6.Tag = 'uipanel6';
            app.GridLayout7 = uigridlayout(app.uipanel6);
            app.uipanel3 = uipanel(app.GridLayout7);
            app.uipanel3.Title = 'Engine States';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout9 = uigridlayout(app.uipanel3);
            app.engineListbox = uilistbox(app.GridLayout9);
            app.engineListbox.Items = {'Listbox'};
            app.engineListbox.ValueChangedFcn = createCallbackFcn(app, @engineListbox_Callback, true);
            app.engineListbox.Tag = 'engineListbox';
            app.engineListbox.Value = 'Listbox';
            app.engineActiveCheckbox = uicheckbox(app.GridLayout9);
            app.engineActiveCheckbox.ValueChangedFcn = createCallbackFcn(app, @engineActiveCheckbox_Callback, true);
            app.engineActiveCheckbox.Tag = 'engineActiveCheckbox';
            app.engineActiveCheckbox.Text = 'Engine is Active?';
            app.uipanel4 = uipanel(app.GridLayout7);
            app.uipanel4.Title = 'Engine to Tank Connection States';
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout10 = uigridlayout(app.uipanel4);
            app.connListbox = uilistbox(app.GridLayout10);
            app.connListbox.Items = {'Listbox'};
            app.connListbox.ValueChangedFcn = createCallbackFcn(app, @connListbox_Callback, true);
            app.connListbox.Tag = 'connListbox';
            app.connListbox.Value = 'Listbox';
            app.connCheckbox = uicheckbox(app.GridLayout10);
            app.connCheckbox.ValueChangedFcn = createCallbackFcn(app, @connCheckbox_Callback, true);
            app.connCheckbox.Tag = 'connCheckbox';
            app.connCheckbox.Text = 'Connection is Active?';
            app.uipanel5 = uipanel(app.GridLayout7);
            app.uipanel5.Title = 'Tank to Tank Connection States';
            app.uipanel5.Tag = 'uipanel5';
            app.GridLayout11 = uigridlayout(app.uipanel5);
            app.t2tConnListbox = uilistbox(app.GridLayout11);
            app.t2tConnListbox.Items = {'Listbox'};
            app.t2tConnListbox.ValueChangedFcn = createCallbackFcn(app, @t2tConnListbox_Callback, true);
            app.t2tConnListbox.Tag = 't2tConnListbox';
            app.t2tConnListbox.Value = 'Listbox';
            app.t2tConnCheckbox = uicheckbox(app.GridLayout11);
            app.t2tConnCheckbox.ValueChangedFcn = createCallbackFcn(app, @t2tConnCheckbox_Callback, true);
            app.t2tConnCheckbox.Tag = 't2tConnCheckbox';
            app.t2tConnCheckbox.Text = 'Connection is Active?';
            app.uipanel7 = uipanel(app.GridLayout6);
            app.uipanel7.Title = 'Electical Power States';
            app.uipanel7.Tag = 'uipanel7';
            app.GridLayout8 = uigridlayout(app.uipanel7);
            app.uipanel8 = uipanel(app.GridLayout8);
            app.uipanel8.Title = 'Power Sources States';
            app.uipanel8.Tag = 'uipanel8';
            app.GridLayout12 = uigridlayout(app.uipanel8);
            app.powerSrcListbox = uilistbox(app.GridLayout12);
            app.powerSrcListbox.Items = {'Listbox'};
            app.powerSrcListbox.ValueChangedFcn = createCallbackFcn(app, @powerSrcListbox_Callback, true);
            app.powerSrcListbox.Tag = 'powerSrcListbox';
            app.powerSrcListbox.Value = 'Listbox';
            app.pwrSrcActiveCheckbox = uicheckbox(app.GridLayout12);
            app.pwrSrcActiveCheckbox.ValueChangedFcn = createCallbackFcn(app, @pwrSrcActiveCheckbox_Callback, true);
            app.pwrSrcActiveCheckbox.Tag = 'pwrSrcActiveCheckbox';
            app.pwrSrcActiveCheckbox.Text = 'Power Source is Active?';
            app.uipanel9 = uipanel(app.GridLayout8);
            app.uipanel9.Title = 'Power Sink States';
            app.uipanel9.Tag = 'uipanel9';
            app.GridLayout13 = uigridlayout(app.uipanel9);
            app.powerSinkListbox = uilistbox(app.GridLayout13);
            app.powerSinkListbox.Items = {'Listbox'};
            app.powerSinkListbox.ValueChangedFcn = createCallbackFcn(app, @powerSinkListbox_Callback, true);
            app.powerSinkListbox.Tag = 'powerSinkListbox';
            app.powerSinkListbox.Value = 'Listbox';
            app.pwrSinkActiveCheckbox = uicheckbox(app.GridLayout13);
            app.pwrSinkActiveCheckbox.ValueChangedFcn = createCallbackFcn(app, @pwrSinkActiveCheckbox_Callback, true);
            app.pwrSinkActiveCheckbox.Tag = 'pwrSinkActiveCheckbox';
            app.pwrSinkActiveCheckbox.Text = 'Power Sink is Active?';
            app.uipanel10 = uipanel(app.GridLayout8);
            app.uipanel10.Title = 'Power Storage States';
            app.uipanel10.Tag = 'uipanel10';
            app.GridLayout14 = uigridlayout(app.uipanel10);
            app.powerStorageListbox = uilistbox(app.GridLayout14);
            app.powerStorageListbox.Items = {'Listbox'};
            app.powerStorageListbox.ValueChangedFcn = createCallbackFcn(app, @powerStorageListbox_Callback, true);
            app.powerStorageListbox.Tag = 'powerStorageListbox';
            app.powerStorageListbox.Value = 'Listbox';
            app.pwrStorageActiveCheckbox = uicheckbox(app.GridLayout14);
            app.pwrStorageActiveCheckbox.ValueChangedFcn = createCallbackFcn(app, @pwrStorageActiveCheckbox_Callback, true);
            app.pwrStorageActiveCheckbox.Tag = 'pwrStorageActiveCheckbox';
            app.pwrStorageActiveCheckbox.Text = 'Power Storage is Active?';
            % Show the figure after all components are created
            app.lvd_EditLvAndStagesStatesGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditLvAndStagesStatesGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditLvAndStagesStatesGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditLvAndStagesStatesGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditLvAndStagesStatesGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditLvAndStagesStatesGUI)
        end
    end
end