classdef lvd_EditSolarSailSolarRadPressModel_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditSolarRadiationPressurePropertiesUIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout2                  matlab.ui.container.GridLayout
        cancelButton                 matlab.ui.control.Button
        saveCloseButton              matlab.ui.control.Button
        ModelPropertiesPanel         matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        GridLayout7                  matlab.ui.container.GridLayout
        Image6                       matlab.ui.control.Image
        Label                        matlab.ui.control.Label
        SolarSailPropertiesPanel     matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        SailOrientationSpacecraftBodyFrameNormalUnitVectorPanel  matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        Image3                       matlab.ui.control.Image
        ZNormCompEdit                matlab.ui.control.EditField
        YNormCompEdit                matlab.ui.control.EditField
        XNormCompEdit                matlab.ui.control.EditField
        text6_11                     matlab.ui.control.Label
        ZLabel                       matlab.ui.control.Label
        YLabel                       matlab.ui.control.Label
        XLabel                       matlab.ui.control.Label
        SailMaterialPropertiesPanel  matlab.ui.container.Panel
        GridLayout3_2                matlab.ui.container.GridLayout
        Image5                       matlab.ui.control.Image
        Image4                       matlab.ui.control.Image
        stageName_12                 matlab.ui.control.Label
        stageName_11                 matlab.ui.control.Label
        stageName_10                 matlab.ui.control.Label
        CoeffDiffuseReflectEdit      matlab.ui.control.EditField
        text6_10                     matlab.ui.control.Label
        text6_9                      matlab.ui.control.Label
        CoeffSpecularReflectEdit     matlab.ui.control.EditField
        Image4_2                     matlab.ui.control.Image
        Image3_2                     matlab.ui.control.Image
        text6_8                      matlab.ui.control.Label
        AreaEdit                     matlab.ui.control.EditField
        stageName_8                  matlab.ui.control.Label
        text6_7                      matlab.ui.control.Label
        CoeffAbsorbEdit              matlab.ui.control.EditField
        SolarPropertiesPanel         matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        Image2                       matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        text6_2                      matlab.ui.control.Label
        SolarFluxReFDistEdit         matlab.ui.control.EditField
        stageName_2                  matlab.ui.control.Label
        text6                        matlab.ui.control.Label
        RefSolarFluxEdit             matlab.ui.control.EditField
        stageName                    matlab.ui.control.Label
        text2                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        srpModel SolarSailSolarRadiationPressureModel
        output AppDesignerGUIOutput
    end
    
    methods (Access = private)
        function populateGUI(app)
            app.RefSolarFluxEdit.Value = fullAccNum2Str(app.srpModel.refSolarFlux);
            app.SolarFluxReFDistEdit.Value = fullAccNum2Str(app.srpModel.solarFluxRefDist);

            app.CoeffAbsorbEdit.Value = fullAccNum2Str(app.srpModel.cRa);
            app.CoeffSpecularReflectEdit.Value = fullAccNum2Str(app.srpModel.cRs);
            app.CoeffDiffuseReflectEdit.Value = fullAccNum2Str(app.srpModel.cRd);
            app.AreaEdit.Value = fullAccNum2Str(app.srpModel.area);
            
            app.XNormCompEdit.Value = fullAccNum2Str(app.srpModel.areaBodyFrameNormVector(1));
            app.YNormCompEdit.Value = fullAccNum2Str(app.srpModel.areaBodyFrameNormVector(2));
            app.ZNormCompEdit.Value = fullAccNum2Str(app.srpModel.areaBodyFrameNormVector(3));
        end
        
        function saveAndClose(app)
            app.srpModel.refSolarFlux = str2double(app.RefSolarFluxEdit.Value);
            app.srpModel.solarFluxRefDist = str2double(app.SolarFluxReFDistEdit.Value);

            app.srpModel.cRa = str2double(app.CoeffAbsorbEdit.Value);
            app.srpModel.cRs = str2double(app.CoeffSpecularReflectEdit.Value);
            app.srpModel.cRd = str2double(app.CoeffDiffuseReflectEdit.Value);
            app.srpModel.area = str2double(app.AreaEdit.Value);

            app.srpModel.areaBodyFrameNormVector(1) = str2double(app.XNormCompEdit.Value);
            app.srpModel.areaBodyFrameNormVector(2) = str2double(app.YNormCompEdit.Value);
            app.srpModel.areaBodyFrameNormVector(3) = str2double(app.ZNormCompEdit.Value);
            app.srpModel.areaBodyFrameNormVector = normVector(app.srpModel.areaBodyFrameNormVector);

            app.output.output = {true};
            close(app.EditSolarRadiationPressurePropertiesUIFigure);
        end
        
        function errMsg = validateInputs(app)
            errMsg = {};

            value = str2double(app.RefSolarFluxEdit.Value);
            enteredStr = app.RefSolarFluxEdit.Value;
            numberName = 'Reference Solar Flux';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(app.SolarFluxReFDistEdit.Value);
            enteredStr = app.SolarFluxReFDistEdit.Value;
            numberName = 'Solar Flux Reference Distance';
            lb = 1;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            cra = str2double(app.CoeffAbsorbEdit.Value);
            enteredStr = app.CoeffAbsorbEdit.Value;
            numberName = 'Spacecraft Absorption Coefficient';
            lb = 0;
            ub = 1;
            isInt = false;
            errMsg = validateNumber(cra, numberName, lb, ub, isInt, errMsg, enteredStr);

            crs = str2double(app.CoeffSpecularReflectEdit.Value);
            enteredStr = app.CoeffSpecularReflectEdit.Value;
            numberName = 'Spacecraft Specular Reflectivity Coefficient';
            lb = 0;
            ub = 1;
            isInt = false;
            errMsg = validateNumber(crs, numberName, lb, ub, isInt, errMsg, enteredStr);

            crd = str2double(app.CoeffDiffuseReflectEdit.Value);
            enteredStr = app.CoeffDiffuseReflectEdit.Value;
            numberName = 'Spacecraft Diffuse Reflectivity Coefficient';
            lb = 0;
            ub = 1;
            isInt = false;
            errMsg = validateNumber(crd, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(app.AreaEdit.Value);
            enteredStr = app.AreaEdit.Value;
            numberName = 'Solar Sail Reflective Area';
            lb = eps;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(app.XNormCompEdit.Value);
            enteredStr = app.XNormCompEdit.Value;
            numberName = 'Solar Sail Normal Vector X Component';
            lb = -1;
            ub = 1;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(app.YNormCompEdit.Value);
            enteredStr = app.YNormCompEdit.Value;
            numberName = 'Solar Sail Normal Vector Y Component';
            lb = -1;
            ub = 1;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(app.ZNormCompEdit.Value);
            enteredStr = app.ZNormCompEdit.Value;
            numberName = 'Solar Sail Normal Vector Z Component';
            lb = -1;
            ub = 1;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                if(abs(cra + crs + crd - 1) > 1E-6)
                    errMsg{end+1} = 'The sum of the three coefficients must be equal to 1.0 (with a tolerance of 1E-6).';
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, srpModel, output)
            arguments
                app(1,1) lvd_EditSolarSailSolarRadPressModel_App
                srpModel(1,1) SolarSailSolarRadiationPressureModel
                output(1,1) AppDesignerGUIOutput
            end
            
            centerUIFigure(app.EditSolarRadiationPressurePropertiesUIFigure);
            applySelectedThemeToApp(app);

            app.srpModel = srpModel;
            app.output = output;

            app.populateGUI();

            uiwait(app.EditSolarRadiationPressurePropertiesUIFigure);
        end

        % Button pushed function: saveCloseButton
        function saveCloseButtonPushed(app, event)
            errMsg = validateInputs(app);

            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.EditSolarRadiationPressurePropertiesUIFigure, errMsg, 'Invalid SRP Model Inputs', "Icon","error");
            end
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            close(app.EditSolarRadiationPressurePropertiesUIFigure);
        end

        % Window key press function: 
        % EditSolarRadiationPressurePropertiesUIFigure
        function EditSolarRadiationPressurePropertiesUIFigureWindowKeyPress(app, event)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(event.Key)
                case 'return'
                    saveCloseButtonPushed(app, evt);
                case 'enter'
                    saveCloseButtonPushed(app, evt);
                case 'escape'
                    close(app.EditSolarRadiationPressurePropertiesUIFigure);
            end
        end

        % Value changed function: RefSolarFluxEdit
        function RefSolarFluxEditValueChanged(app, event)
            newInput = app.RefSolarFluxEdit.Value;
            newInput = attemptStrEval(newInput);
            app.RefSolarFluxEdit.Value = newInput;
        end

        % Value changed function: SolarFluxReFDistEdit
        function SolarFluxReFDistEditValueChanged(app, event)
            newInput = app.SolarFluxReFDistEdit.Value;
            newInput = attemptStrEval(newInput);
            app.SolarFluxReFDistEdit.Value = newInput;
        end

        % Value changed function: CoeffAbsorbEdit
        function CoeffAbsorbEditValueChanged(app, event)
            newInput = app.CoeffAbsorbEdit.Value;
            newInput = attemptStrEval(newInput);
            app.CoeffAbsorbEdit.Value = newInput;
        end

        % Value changed function: CoeffSpecularReflectEdit
        function CoeffSpecularReflectEditValueChanged(app, event)
            newInput = app.CoeffSpecularReflectEdit.Value;
            newInput = attemptStrEval(newInput);
            app.CoeffSpecularReflectEdit.Value = newInput;
        end

        % Value changed function: CoeffDiffuseReflectEdit
        function CoeffDiffuseReflectEditValueChanged(app, event)
            newInput = app.CoeffDiffuseReflectEdit.Value;
            newInput = attemptStrEval(newInput);
            app.CoeffDiffuseReflectEdit.Value = newInput;
        end

        % Value changed function: AreaEdit
        function AreaEditValueChanged(app, event)
            newInput = app.AreaEdit.Value;
            newInput = attemptStrEval(newInput);
            app.AreaEdit.Value = newInput;
        end

        % Value changed function: XNormCompEdit
        function XNormCompEditValueChanged(app, event)
            newInput = app.XNormCompEdit.Value;
            newInput = attemptStrEval(newInput);
            app.XNormCompEdit.Value = newInput;
        end

        % Value changed function: YNormCompEdit
        function YNormCompEditValueChanged(app, event)
            newInput = app.YNormCompEdit.Value;
            newInput = attemptStrEval(newInput);
            app.YNormCompEdit.Value = newInput;
        end

        % Value changed function: ZNormCompEdit
        function ZNormCompEditValueChanged(app, event)
            newInput = app.ZNormCompEdit.Value;
            newInput = attemptStrEval(newInput);
            app.ZNormCompEdit.Value = newInput;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditSolarRadiationPressurePropertiesUIFigure = uifigure('Visible', 'off');
            app.EditSolarRadiationPressurePropertiesUIFigure.Name = 'Edit Solar Radiation Pressure Properties';
            app.EditSolarRadiationPressurePropertiesUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.EditSolarRadiationPressurePropertiesUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @EditSolarRadiationPressurePropertiesUIFigureWindowKeyPress, true);
            app.GridLayout = uigridlayout(app.EditSolarRadiationPressurePropertiesUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Solar Radiation Pressure Properties';
            app.ModelPropertiesPanel = uipanel(app.GridLayout);
            app.ModelPropertiesPanel.Title = 'Model Properties';
            app.GridLayout4 = uigridlayout(app.ModelPropertiesPanel);
            app.SolarPropertiesPanel = uipanel(app.GridLayout4);
            app.SolarPropertiesPanel.Title = 'Solar Properties';
            app.GridLayout3 = uigridlayout(app.SolarPropertiesPanel);
            app.stageName = uilabel(app.GridLayout3);
            app.stageName.Interpreter = 'latex';
            app.stageName.Tag = 'stageName';
            app.stageName.Tooltip = {'The amount of solar flux at the referene distance from the Sun.  For the real solar system, this is 1367 W/m^2 at 1 AU.'};
            app.stageName.Text = 'Ref. Solar Flux';
            app.RefSolarFluxEdit = uieditfield(app.GridLayout3, 'text');
            app.RefSolarFluxEdit.ValueChangedFcn = createCallbackFcn(app, @RefSolarFluxEditValueChanged, true);
            app.RefSolarFluxEdit.Tooltip = {'The amount of solar flux at the referene distance from the Sun.  For the real solar system, this is 1367 W/m^2 at 1 AU.'};
            app.text6 = uilabel(app.GridLayout3);
            app.text6.Interpreter = 'latex';
            app.text6.Tag = 'text6';
            app.text6.Text = '\[W/m^2\]';
            app.stageName_2 = uilabel(app.GridLayout3);
            app.stageName_2.Interpreter = 'latex';
            app.stageName_2.Tag = 'stageName';
            app.stageName_2.Tooltip = {'The distance from the Sun that the reference solar flux occurs at.  For the real solar system, this is 1 AU.'};
            app.stageName_2.Text = 'Solar Flux Ref Distane';
            app.SolarFluxReFDistEdit = uieditfield(app.GridLayout3, 'text');
            app.SolarFluxReFDistEdit.ValueChangedFcn = createCallbackFcn(app, @SolarFluxReFDistEditValueChanged, true);
            app.SolarFluxReFDistEdit.Tooltip = {'The distance from the Sun that the reference solar flux occurs at.  For the real solar system, this is 1 AU.'};
            app.text6_2 = uilabel(app.GridLayout3);
            app.text6_2.Interpreter = 'latex';
            app.text6_2.Tag = 'text6';
            app.text6_2.Text = 'km';
            app.Image = uiimage(app.GridLayout3);
            app.Image.ImageSource = 'sun.png';
            app.Image2 = uiimage(app.GridLayout3);
            app.Image2.ImageSource = 'line.png';
            app.SolarSailPropertiesPanel = uipanel(app.GridLayout4);
            app.SolarSailPropertiesPanel.Title = 'Solar Sail Properties';
            app.GridLayout5 = uigridlayout(app.SolarSailPropertiesPanel);
            app.SailMaterialPropertiesPanel = uipanel(app.GridLayout5);
            app.SailMaterialPropertiesPanel.Title = 'Sail Material Properties';
            app.GridLayout3_2 = uigridlayout(app.SailMaterialPropertiesPanel);
            app.CoeffAbsorbEdit = uieditfield(app.GridLayout3_2, 'text');
            app.CoeffAbsorbEdit.ValueChangedFcn = createCallbackFcn(app, @CoeffAbsorbEditValueChanged, true);
            app.CoeffAbsorbEdit.Tooltip = {'The coefficient of absorption of the solar sail material.  Must be between 0 and 1 inclusive.'; ''; 'It is required that C_ra + C_rs + C_rd = 1.'};
            app.text6_7 = uilabel(app.GridLayout3_2);
            app.text6_7.Interpreter = 'latex';
            app.text6_7.Tag = 'text6';
            app.text6_7.Text = '';
            app.stageName_8 = uilabel(app.GridLayout3_2);
            app.stageName_8.Interpreter = 'latex';
            app.stageName_8.Tag = 'stageName';
            app.stageName_8.Tooltip = {'The area of the solar sail that sunlight hits.'};
            app.stageName_8.Text = 'Sail Reflective Area';
            app.AreaEdit = uieditfield(app.GridLayout3_2, 'text');
            app.AreaEdit.ValueChangedFcn = createCallbackFcn(app, @AreaEditValueChanged, true);
            app.AreaEdit.Tooltip = {'The area of the solar sail that sunlight hits.'};
            app.text6_8 = uilabel(app.GridLayout3_2);
            app.text6_8.Interpreter = 'latex';
            app.text6_8.Tag = 'text6';
            app.text6_8.Text = '\[m^2\]';
            app.Image3_2 = uiimage(app.GridLayout3_2);
            app.Image3_2.ImageSource = 'specular-reflection_1.png';
            app.Image4_2 = uiimage(app.GridLayout3_2);
            app.Image4_2.ImageSource = 'sphere_blue.png';
            app.CoeffSpecularReflectEdit = uieditfield(app.GridLayout3_2, 'text');
            app.CoeffSpecularReflectEdit.ValueChangedFcn = createCallbackFcn(app, @CoeffSpecularReflectEditValueChanged, true);
            app.CoeffSpecularReflectEdit.Tooltip = {'The coefficient of specular reflection of the solar sail material.  Must be between 0 and 1 inclusive.'; ''; 'It is required that C_ra + C_rs + C_rd = 1.'};
            app.text6_9 = uilabel(app.GridLayout3_2);
            app.text6_9.Interpreter = 'latex';
            app.text6_9.Tag = 'text6';
            app.text6_9.Text = '';
            app.text6_10 = uilabel(app.GridLayout3_2);
            app.text6_10.Interpreter = 'latex';
            app.text6_10.Tag = 'text6';
            app.text6_10.Text = '';
            app.CoeffDiffuseReflectEdit = uieditfield(app.GridLayout3_2, 'text');
            app.CoeffDiffuseReflectEdit.ValueChangedFcn = createCallbackFcn(app, @CoeffDiffuseReflectEditValueChanged, true);
            app.CoeffDiffuseReflectEdit.Tooltip = {'The coefficient of diffuse reflection of the solar sail material.  Must be between 0 and 1 inclusive.'; ''; 'It is required that C_ra + C_rs + C_rd = 1.'};
            app.stageName_10 = uilabel(app.GridLayout3_2);
            app.stageName_10.Interpreter = 'latex';
            app.stageName_10.Tag = 'stageName';
            app.stageName_10.Tooltip = {'The coefficient of specular reflection of the solar sail material.  Must be between 0 and 1 inclusive.'; ''; 'It is required that C_ra + C_rs + C_rd = 1.'};
            app.stageName_10.Text = 'Coeff. of Specular Reflectivity \(C_{rs}\)';
            app.stageName_11 = uilabel(app.GridLayout3_2);
            app.stageName_11.Interpreter = 'latex';
            app.stageName_11.Tag = 'stageName';
            app.stageName_11.Tooltip = {''};
            app.stageName_11.Text = 'Coeff. of Absorption \(C_{ra}\)';
            app.stageName_12 = uilabel(app.GridLayout3_2);
            app.stageName_12.Interpreter = 'latex';
            app.stageName_12.Tag = 'stageName';
            app.stageName_12.Tooltip = {'The coefficient of diffuse reflection of the solar sail material.  Must be between 0 and 1 inclusive.'; ''; 'It is required that C_ra + C_rs + C_rd = 1.'};
            app.stageName_12.Text = 'Coeff. of Diffuse Reflectivity \(C_{rd}\)';
            app.Image4 = uiimage(app.GridLayout3_2);
            app.Image4.ImageSource = 'black-hole.png';
            app.Image5 = uiimage(app.GridLayout3_2);
            app.Image5.ImageSource = 'diffusion.png';
            app.SailOrientationSpacecraftBodyFrameNormalUnitVectorPanel = uipanel(app.GridLayout5);
            app.SailOrientationSpacecraftBodyFrameNormalUnitVectorPanel.Title = 'Sail Orientation (Spacecraft Body Frame Normal Unit Vector)';
            app.GridLayout6 = uigridlayout(app.SailOrientationSpacecraftBodyFrameNormalUnitVectorPanel);
            app.XLabel = uilabel(app.GridLayout6);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.GridLayout6);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.GridLayout6);
            app.ZLabel.Text = 'Z';
            app.text6_11 = uilabel(app.GridLayout6);
            app.text6_11.Interpreter = 'latex';
            app.text6_11.Tag = 'text6';
            app.text6_11.Text = '';
            app.XNormCompEdit = uieditfield(app.GridLayout6, 'text');
            app.XNormCompEdit.ValueChangedFcn = createCallbackFcn(app, @XNormCompEditValueChanged, true);
            app.XNormCompEdit.Tooltip = {'The X component of the solar sail''s normal vector in the spacecraft''s body frame.'};
            app.YNormCompEdit = uieditfield(app.GridLayout6, 'text');
            app.YNormCompEdit.ValueChangedFcn = createCallbackFcn(app, @YNormCompEditValueChanged, true);
            app.YNormCompEdit.Tooltip = {'The Y component of the solar sail''s normal vector in the spacecraft''s body frame.'};
            app.ZNormCompEdit = uieditfield(app.GridLayout6, 'text');
            app.ZNormCompEdit.ValueChangedFcn = createCallbackFcn(app, @ZNormCompEditValueChanged, true);
            app.ZNormCompEdit.Tooltip = {'The Z component of the solar sail''s normal vector in the spacecraft''s body frame.'};
            app.Image3 = uiimage(app.GridLayout6);
            app.Image3.ImageSource = 'angle_arrow.png';
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.Label = uilabel(app.GridLayout7);
            app.Label.Text = 'See Vallado''s "Fundementals of Astrodynamics" Section 8.6.4 "Solar Radiation Pressure" for details of this model.';
            app.Image6 = uiimage(app.GridLayout7);
            app.Image6.ImageSource = 'yellowwarning.png';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveCloseButtonPushed, true);
            app.saveCloseButton.Tag = 'saveCloseButton';
            app.saveCloseButton.Icon = 'save-file.png';
            app.saveCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.EditSolarRadiationPressurePropertiesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditSolarSailSolarRadPressModel_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditSolarRadiationPressurePropertiesUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditSolarRadiationPressurePropertiesUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditSolarRadiationPressurePropertiesUIFigure)
        end
    end
end