classdef lvd_EditSphericalSolarRadPressModel_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditSolarRadiationPressurePropertiesUIFigure  matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        GridLayout2           matlab.ui.container.GridLayout
        cancelButton          matlab.ui.control.Button
        saveCloseButton       matlab.ui.control.Button
        ModelPropertiesPanel  matlab.ui.container.Panel
        GridLayout4           matlab.ui.container.GridLayout
        GridLayout6           matlab.ui.container.GridLayout
        SpacecraftMaterialPropertiesPanel  matlab.ui.container.Panel
        GridLayout3           matlab.ui.container.GridLayout
        Image4                matlab.ui.control.Image
        Image3                matlab.ui.control.Image
        text6_4               matlab.ui.control.Label
        AreaEdit              matlab.ui.control.EditField
        stageName_4           matlab.ui.control.Label
        text6_3               matlab.ui.control.Label
        CoeffReflectEdit      matlab.ui.control.EditField
        stageName_3           matlab.ui.control.Label
        SolarPropertiesPanel  matlab.ui.container.Panel
        GridLayout7           matlab.ui.container.GridLayout
        text6_2               matlab.ui.control.Label
        SolarFluxReFDistEdit  matlab.ui.control.EditField
        stageName_2           matlab.ui.control.Label
        Image2                matlab.ui.control.Image
        text6                 matlab.ui.control.Label
        RefSolarFluxEdit      matlab.ui.control.EditField
        stageName             matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        GridLayout5           matlab.ui.container.GridLayout
        Label                 matlab.ui.control.Label
        Image5                matlab.ui.control.Image
        text2                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        srpModel SphericalSolarRadiationPressureModel
        output AppDesignerGUIOutput
    end
    
    methods (Access = private)
        function populateGUI(app)
            app.RefSolarFluxEdit.Value = fullAccNum2Str(app.srpModel.refSolarFlux);
            app.SolarFluxReFDistEdit.Value = fullAccNum2Str(app.srpModel.solarFluxRefDist);
            app.CoeffReflectEdit.Value = fullAccNum2Str(app.srpModel.cR);
            app.AreaEdit.Value = fullAccNum2Str(app.srpModel.area);
        end
        
        function saveAndClose(app)
            app.srpModel.refSolarFlux = str2double(app.RefSolarFluxEdit.Value);
            app.srpModel.solarFluxRefDist = str2double(app.SolarFluxReFDistEdit.Value);
            app.srpModel.cR = str2double(app.CoeffReflectEdit.Value);
            app.srpModel.area = str2double(app.AreaEdit.Value);

            app.output.output = {true};
            close(app.EditSolarRadiationPressurePropertiesUIFigure);
        end
        
        function errMsg = validateInputs(app)
            errMsg = {};

            value = str2double(app.RefSolarFluxEdit.Value);
            enteredStr = app.RefSolarFluxEdit.Value;
            numberName = 'Reference Solar Flux';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(app.SolarFluxReFDistEdit.Value);
            enteredStr = app.SolarFluxReFDistEdit.Value;
            numberName = 'Solar Flux Reference Distance';
            lb = 1;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(app.CoeffReflectEdit.Value);
            enteredStr = app.CoeffReflectEdit.Value;
            numberName = 'Spacecraft Reflectivity Coefficient';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(app.AreaEdit.Value);
            enteredStr = app.AreaEdit.Value;
            numberName = 'Spacecraft Reflective Area';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, srpModel, output)
            arguments
                app(1,1) lvd_EditSphericalSolarRadPressModel_App
                srpModel(1,1) SphericalSolarRadiationPressureModel
                output(1,1) AppDesignerGUIOutput
            end
            
            centerUIFigure(app.EditSolarRadiationPressurePropertiesUIFigure);
            applySelectedThemeToApp(app)

            app.srpModel = srpModel;
            app.output = output;

            app.populateGUI();

            uiwait(app.EditSolarRadiationPressurePropertiesUIFigure);
        end

        % Button pushed function: saveCloseButton
        function saveCloseButtonPushed(app, event)
            errMsg = validateInputs(app);

            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.EditSolarRadiationPressurePropertiesUIFigure, errMsg, 'Invalid SRP Model Inputs', "Icon","error");
            end
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            close(app.EditSolarRadiationPressurePropertiesUIFigure);
        end

        % Window key press function: 
        % EditSolarRadiationPressurePropertiesUIFigure
        function EditSolarRadiationPressurePropertiesUIFigureWindowKeyPress(app, event)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            switch(event.Key)
                case 'return'
                    saveCloseButtonPushed(app, evt);
                case 'enter'
                    saveCloseButtonPushed(app, evt);
                case 'escape'
                    close(app.EditSolarRadiationPressurePropertiesUIFigure);
            end
        end

        % Value changed function: RefSolarFluxEdit
        function RefSolarFluxEditValueChanged(app, event)
            newInput = app.RefSolarFluxEdit.Value;
            newInput = attemptStrEval(newInput);
            app.RefSolarFluxEdit.Value = newInput;
        end

        % Value changed function: SolarFluxReFDistEdit
        function SolarFluxReFDistEditValueChanged(app, event)
            newInput = app.SolarFluxReFDistEdit.Value;
            newInput = attemptStrEval(newInput);
            app.SolarFluxReFDistEdit.Value = newInput;
        end

        % Value changed function: CoeffReflectEdit
        function CoeffReflectEditValueChanged(app, event)
            newInput = app.CoeffReflectEdit.Value;
            newInput = attemptStrEval(newInput);
            app.CoeffReflectEdit.Value = newInput;
        end

        % Value changed function: AreaEdit
        function AreaEditValueChanged(app, event)
            newInput = app.AreaEdit.Value;
            newInput = attemptStrEval(newInput);
            app.AreaEdit.Value = newInput;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditSolarRadiationPressurePropertiesUIFigure = uifigure('Visible', 'off');
            app.EditSolarRadiationPressurePropertiesUIFigure.Name = 'Edit Solar Radiation Pressure Properties';
            app.EditSolarRadiationPressurePropertiesUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.EditSolarRadiationPressurePropertiesUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @EditSolarRadiationPressurePropertiesUIFigureWindowKeyPress, true);
            app.GridLayout = uigridlayout(app.EditSolarRadiationPressurePropertiesUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Solar Radiation Pressure Properties';
            app.ModelPropertiesPanel = uipanel(app.GridLayout);
            app.ModelPropertiesPanel.Title = 'Model Properties';
            app.GridLayout4 = uigridlayout(app.ModelPropertiesPanel);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.Image5 = uiimage(app.GridLayout5);
            app.Image5.ImageSource = 'yellowwarning.png';
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = 'See Vallado''s "Fundementals of Astrodynamics" Section 8.6.4 "Solar Radiation Pressure" for details of this model.';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.SolarPropertiesPanel = uipanel(app.GridLayout6);
            app.SolarPropertiesPanel.Title = 'Solar Properties';
            app.GridLayout7 = uigridlayout(app.SolarPropertiesPanel);
            app.Image = uiimage(app.GridLayout7);
            app.Image.ImageSource = 'sun.png';
            app.stageName = uilabel(app.GridLayout7);
            app.stageName.Interpreter = 'latex';
            app.stageName.Tag = 'stageName';
            app.stageName.Tooltip = {'The amount of solar flux at the referene distance from the Sun.  For the real solar system, this is 1367 W/m^2 at 1 AU.'};
            app.stageName.Text = 'Ref. Solar Flux';
            app.RefSolarFluxEdit = uieditfield(app.GridLayout7, 'text');
            app.RefSolarFluxEdit.ValueChangedFcn = createCallbackFcn(app, @RefSolarFluxEditValueChanged, true);
            app.RefSolarFluxEdit.Tooltip = {'The amount of solar flux at the referene distance from the Sun.  For the real solar system, this is 1367 W/m^2 at 1 AU.'};
            app.text6 = uilabel(app.GridLayout7);
            app.text6.Interpreter = 'latex';
            app.text6.Tag = 'text6';
            app.text6.Text = '\[W/m^2\]';
            app.Image2 = uiimage(app.GridLayout7);
            app.Image2.ImageSource = 'line.png';
            app.stageName_2 = uilabel(app.GridLayout7);
            app.stageName_2.Interpreter = 'latex';
            app.stageName_2.Tag = 'stageName';
            app.stageName_2.Tooltip = {'The distance from the Sun that the reference solar flux occurs at.  For the real solar system, this is 1 AU.'};
            app.stageName_2.Text = 'Solar Flux Ref Distane';
            app.SolarFluxReFDistEdit = uieditfield(app.GridLayout7, 'text');
            app.SolarFluxReFDistEdit.ValueChangedFcn = createCallbackFcn(app, @SolarFluxReFDistEditValueChanged, true);
            app.SolarFluxReFDistEdit.Tooltip = {'The distance from the Sun that the reference solar flux occurs at.  For the real solar system, this is 1 AU.'};
            app.text6_2 = uilabel(app.GridLayout7);
            app.text6_2.Interpreter = 'latex';
            app.text6_2.Tag = 'text6';
            app.text6_2.Text = 'km';
            app.SpacecraftMaterialPropertiesPanel = uipanel(app.GridLayout6);
            app.SpacecraftMaterialPropertiesPanel.Title = 'Spacecraft Material Properties';
            app.GridLayout3 = uigridlayout(app.SpacecraftMaterialPropertiesPanel);
            app.stageName_3 = uilabel(app.GridLayout3);
            app.stageName_3.Interpreter = 'latex';
            app.stageName_3.Tag = 'stageName';
            app.stageName_3.Tooltip = {'Describes how reflective the body is.  A value of 0.0 means the vehicle is transparent.  A value of 1.0 means that the vehicle absorbs all incident light.  A value of 2.0 means that the vehicle reflects all incident light.  Values between 0.0 and 1.0 mean that the vehicle is somewhat transparent, and between 1.0 and 2.0 means that the vehicle is somewhat reflective.'};
            app.stageName_3.Text = 'Coeff. of Reflectivity \(C_r\)';
            app.CoeffReflectEdit = uieditfield(app.GridLayout3, 'text');
            app.CoeffReflectEdit.ValueChangedFcn = createCallbackFcn(app, @CoeffReflectEditValueChanged, true);
            app.CoeffReflectEdit.Tooltip = {'Describes how reflective the body is.  A value of 0.0 means the vehicle is transparent.  A value of 1.0 means that the vehicle absorbs all incident light.  A value of 2.0 means that the vehicle reflects all incident light.  Values between 0.0 and 1.0 mean that the vehicle is somewhat transparent, and between 1.0 and 2.0 means that the vehicle is somewhat reflective.'};
            app.text6_3 = uilabel(app.GridLayout3);
            app.text6_3.Interpreter = 'latex';
            app.text6_3.Tag = 'text6';
            app.text6_3.Text = '';
            app.stageName_4 = uilabel(app.GridLayout3);
            app.stageName_4.Interpreter = 'latex';
            app.stageName_4.Tag = 'stageName';
            app.stageName_4.Tooltip = {'The area of the spacecraft body that sunlight hits.  Because the vehicle is modeled as a sphere here, this is a constant regardless of vehicle orientation.'};
            app.stageName_4.Text = 'Reflective Area';
            app.AreaEdit = uieditfield(app.GridLayout3, 'text');
            app.AreaEdit.ValueChangedFcn = createCallbackFcn(app, @AreaEditValueChanged, true);
            app.AreaEdit.Tooltip = {'The area of the spacecraft body that sunlight hits.  Because the vehicle is modeled as a sphere here, this is a constant regardless of vehicle orientation.'};
            app.text6_4 = uilabel(app.GridLayout3);
            app.text6_4.Interpreter = 'latex';
            app.text6_4.Tag = 'text6';
            app.text6_4.Text = '\[m^2\]';
            app.Image3 = uiimage(app.GridLayout3);
            app.Image3.ImageSource = 'specular-reflection_1.png';
            app.Image4 = uiimage(app.GridLayout3);
            app.Image4.ImageSource = 'sphere_blue.png';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveCloseButtonPushed, true);
            app.saveCloseButton.Tag = 'saveCloseButton';
            app.saveCloseButton.Icon = 'save-file.png';
            app.saveCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.EditSolarRadiationPressurePropertiesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditSphericalSolarRadPressModel_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditSolarRadiationPressurePropertiesUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditSolarRadiationPressurePropertiesUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditSolarRadiationPressurePropertiesUIFigure)
        end
    end
end