classdef lvd_EditSrpModels_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditSrpPropertiesUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout2_2              matlab.ui.container.GridLayout
        cancelButton               matlab.ui.control.Button
        saveCloseButton            matlab.ui.control.Button
        GridLayout2                matlab.ui.container.GridLayout
        SolarRadiationPressureModelPanel  matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        EditSrpModelButton         matlab.ui.control.Button
        ModelDescLabel             matlab.ui.control.Label
        SrpModelCombo              matlab.ui.control.DropDown
        text2                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput
        lvdData LvdData
        srpModel LaunchVehicleSolarRadPressState
    end
    
    methods (Access = private)
        function populateGUI(app)
            [listBoxStr, m] = SolarRadPressModelEnum.getListBoxStr();
            app.SrpModelCombo.Items = listBoxStr;
            app.SrpModelCombo.ItemsData = m;
            app.SrpModelCombo.Value = app.srpModel.srpModelObj.enum;

            app.ModelDescLabel.Text = app.srpModel.srpModelObj.enum.desc;
        end

        function saveAndClose(app) 
            enum = app.SrpModelCombo.Value;
            switch enum
                case SolarRadPressModelEnum.Spherical
                    app.srpModel.srpModelObj = app.srpModel.srpSphericalModel;

                case SolarRadPressModelEnum.SolarSail
                    app.srpModel.srpModelObj = app.srpModel.srpSolarSailModel;

                otherwise
                    error('Unknown solar radiation pressure model type: %s', enum.name);
            end

            app.output.output{1} = true;
            close(app.EditSrpPropertiesUIFigure);
        end
        
        function errMsg = validateInputs(app)
            errMsg = {};
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, srpModel, lvdData, out)
            centerUIFigure(app.EditSrpPropertiesUIFigure);
            applySelectedThemeToApp(app);

            app.lvdData = lvdData;
            app.srpModel = srpModel;
            app.output = out;

            app.populateGUI();

            uiwait(app.EditSrpPropertiesUIFigure);
        end

        % Button pushed function: EditSrpModelButton
        function EditSrpModelButtonPushed(app, event)
            enum = app.SrpModelCombo.Value;
            switch enum
                case SolarRadPressModelEnum.Spherical
                    app.srpModel.srpSphericalModel.openEditDialog(app.lvdData);

                case SolarRadPressModelEnum.SolarSail
                    app.srpModel.srpSolarSailModel.openEditDialog(app.lvdData);

                otherwise
                    error('Unknown solar radiation pressure model type: %s', enum.name);
            end
        end

        % Button pushed function: saveCloseButton
        function saveCloseButtonPushed(app, event)
            errMsg = app.validateInputs();

            if(isempty(errMsg))
                app.saveAndClose();
            else
                uialert(app.EditSrpPropertiesUIFigure, errMsg, 'Errors were found while editing solar radiation pressure model properties.', "Icon","error");
            end
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            app.output.output = {false};
            close(app.EditSrpPropertiesUIFigure);
        end

        % Value changed function: SrpModelCombo
        function SrpModelComboValueChanged(app, event)
            value = app.SrpModelCombo.Value;
            app.ModelDescLabel.Text = value.desc;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditSrpPropertiesUIFigure = uifigure('Visible', 'off');
            app.EditSrpPropertiesUIFigure.Name = 'Edit Solar Radiation Pressure Properties';
            app.EditSrpPropertiesUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout = uigridlayout(app.EditSrpPropertiesUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Solar Radiation Pressure Properties';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SolarRadiationPressureModelPanel = uipanel(app.GridLayout2);
            app.SolarRadiationPressureModelPanel.Title = 'Solar Radiation Pressure Model';
            app.GridLayout3 = uigridlayout(app.SolarRadiationPressureModelPanel);
            app.SrpModelCombo = uidropdown(app.GridLayout3);
            app.SrpModelCombo.ValueChangedFcn = createCallbackFcn(app, @SrpModelComboValueChanged, true);
            app.ModelDescLabel = uilabel(app.GridLayout3);
            app.EditSrpModelButton = uibutton(app.GridLayout3, 'push');
            app.EditSrpModelButton.ButtonPushedFcn = createCallbackFcn(app, @EditSrpModelButtonPushed, true);
            app.EditSrpModelButton.Icon = 'yellowSrpArrow.png';
            app.EditSrpModelButton.Text = 'Edit Solar Radiation Pressure Model';
            app.GridLayout2_2 = uigridlayout(app.GridLayout);
            app.saveCloseButton = uibutton(app.GridLayout2_2, 'push');
            app.saveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveCloseButtonPushed, true);
            app.saveCloseButton.Tag = 'saveCloseButton';
            app.saveCloseButton.Icon = 'save-file.png';
            app.saveCloseButton.Text = 'Save & Close';
            app.cancelButton = uibutton(app.GridLayout2_2, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.EditSrpPropertiesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditSrpModels_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditSrpPropertiesUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditSrpPropertiesUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditSrpPropertiesUIFigure)
        end
    end
end