classdef lvd_AdjustCameraGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CameraControlsUIFigure       matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        CameraZoomPanel              matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        CameraZoomSlider             matlab.ui.control.Slider
        CameraRotatePanel            matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        CameraRotateRightLeftSlider  matlab.ui.control.Slider
        CameraRotateUpDownSlider     matlab.ui.control.Slider
        CameraMoveForwardsBackwardsPanel  matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        CameraPanInOutSlider         matlab.ui.control.Slider
        CameraPanPanel               matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        CameraPanUpDownSlider        matlab.ui.control.Slider
        CameraPanRightLeftSlider     matlab.ui.control.Slider
    end

    
    properties (Access = private)
        hAx matlab.ui.control.UIAxes
    end
    
    methods (Access = private)
        
        function resetAllSliderPositions(app)
            app.CameraPanUpDownSlider.Value = 0;
            app.CameraPanRightLeftSlider.Value = 0;
            app.CameraPanInOutSlider.Value = 0;
            app.CameraRotateUpDownSlider.Value = 0;
            app.CameraRotateRightLeftSlider.Value = 0;
            app.CameraZoomSlider.Value = 1;
        end
        
        function [pos, tgt, pos2tgtVect, upVect] = getCameraData(app)
            pos = app.hAx.CameraPosition(:);
            tgt = app.hAx.CameraTarget(:);
            pos2tgtVect = normVector(tgt - pos);
            upVect = normVector(app.hAx.CameraUpVector(:));
        end
        
        function [x,y,z] = getCameraCoordSys(app)
            [~, ~, pos2tgtVect, upVect] = app.getCameraData();
            
            x = pos2tgtVect(:);
            z = upVect(:);
            y = normVector(cross(z,x));
        end
        
        function axesBeingDestroyed(app, src, evt)
            if(isvalid(app) && isvalid(app.CameraControlsUIFigure))
                close(app.CameraControlsUIFigure);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hAx)
            centerUIFigure(app.CameraControlsUIFigure);
            applySelectedThemeToApp(app);
            
            if(nargin >= 2)
                app.hAx = hAx;
            else
                app.hAx = uiaxes(uifigure());
                
                [X,Y,Z] = sphere(30);
                surf(app.hAx, X,Y,Z);
                axis(app.hAx, 'equal');
                axis(app.hAx, 'tight');
                grid(app.hAx, "minor");
                
                app.hAx.CameraPosition = [-2,0,0];
                app.hAx.CameraTarget = [0,0,0];
            end
            
            addlistener(app.hAx,'ObjectBeingDestroyed', @(src,evt) axesBeingDestroyed(app, src, evt));
        end

        % Value changing function: CameraPanUpDownSlider
        function CameraPanUpDownSliderValueChanging(app, event)
            [x,y,z] = app.getCameraCoordSys();
            
            xlims = app.hAx.XLim;
            xRng = xlims(2) - xlims(1);
            
            ylims = app.hAx.YLim;
            yRng = ylims(2) - ylims(1);
            
            zlims = app.hAx.ZLim;
            zRng = zlims(2) - zlims(1);
            
            motionScaleFactor1 = event.Value;
            motionScaleFactor2 = min([xRng, yRng, zRng]);
            
            offset = motionScaleFactor1*motionScaleFactor2*z;
            
            app.hAx.CameraPosition = app.hAx.CameraPosition + offset(:)';
            app.hAx.CameraTarget = app.hAx.CameraTarget + offset(:)';
        end

        % Value changing function: CameraPanRightLeftSlider
        function CameraPanRightLeftSliderValueChanging(app, event)
            [x,y,z] = app.getCameraCoordSys();
            
            xlims = app.hAx.XLim;
            xRng = xlims(2) - xlims(1);
            
            ylims = app.hAx.YLim;
            yRng = ylims(2) - ylims(1);
            
            zlims = app.hAx.ZLim;
            zRng = zlims(2) - zlims(1);
            
            motionScaleFactor1 = event.Value;
            motionScaleFactor2 = min([xRng, yRng, zRng]);
            
            offset = motionScaleFactor1*motionScaleFactor2*(-y);
            
            app.hAx.CameraPosition = app.hAx.CameraPosition + offset(:)';
            app.hAx.CameraTarget = app.hAx.CameraTarget + offset(:)';
        end

        % Value changing function: CameraPanInOutSlider
        function CameraPanInOutSliderValueChanging(app, event)
            [x,y,z] = app.getCameraCoordSys();
            
            xlims = app.hAx.XLim;
            xRng = xlims(2) - xlims(1);
            
            ylims = app.hAx.YLim;
            yRng = ylims(2) - ylims(1);
            
            zlims = app.hAx.ZLim;
            zRng = zlims(2) - zlims(1);
            
            motionScaleFactor1 = event.Value;
            motionScaleFactor2 = min([xRng, yRng, zRng]);
            
            offset = motionScaleFactor1*motionScaleFactor2*x;
            
            app.hAx.CameraPosition = app.hAx.CameraPosition + offset(:)';
%             app.hAx.CameraTarget = app.hAx.CameraTarget + offset(:)';
        end

        % Value changing function: CameraRotateUpDownSlider
        function CameraRotateUpDownSliderValueChanging(app, event)
            changingValue = event.Value;
            
            [pos, tgt, ~, ~] = app.getCameraData();
            [x,y,z] = app.getCameraCoordSys();
            
            M1 = roty(changingValue);
            M2 = [x(:), y(:), z(:)];
            M = M1 * M2;
            
            app.hAx.CameraTarget = pos + norm(tgt-pos)*M(:,1);
        end

        % Value changing function: CameraRotateRightLeftSlider
        function CameraRotateRightLeftSliderValueChanging(app, event)
            changingValue = event.Value;
            
            [pos, tgt, ~, ~] = app.getCameraData();
            [x,y,z] = app.getCameraCoordSys();
            
            M1 = rotz(-changingValue);
            M2 = [x(:), y(:), z(:)];
            M = M1 * M2;
            
            app.hAx.CameraTarget = pos + norm(tgt-pos)*M(:,1);
        end

        % Window button down function: CameraControlsUIFigure
        function CameraControlsUIFigureWindowButtonDown(app, event)
            
        end

        % Window button up function: CameraControlsUIFigure
        function CameraControlsUIFigureWindowButtonUp(app, event)
            app.resetAllSliderPositions();
        end

        % Value changing function: CameraZoomSlider
        function CameraZoomSliderValueChanging(app, event)
            cameraZoomFactor = max(event.Value,0.0001);
            camzoom(app.hAx, cameraZoomFactor);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CameraControlsUIFigure = uifigure('Visible', 'off');
            app.CameraControlsUIFigure.Name = 'Camera Controls';
            app.CameraControlsUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.CameraControlsUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @CameraControlsUIFigureWindowButtonDown, true);
            app.CameraControlsUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @CameraControlsUIFigureWindowButtonUp, true);
            app.GridLayout = uigridlayout(app.CameraControlsUIFigure);
            app.CameraPanPanel = uipanel(app.GridLayout);
            app.CameraPanPanel.Title = 'Camera Pan';
            app.GridLayout2 = uigridlayout(app.CameraPanPanel);
            app.CameraPanRightLeftSlider = uislider(app.GridLayout2);
            app.CameraPanRightLeftSlider.Limits = [-1 1];
            app.CameraPanRightLeftSlider.MajorTicks = [-1 -0.6 -0.2 0.2 0.6 1];
            app.CameraPanRightLeftSlider.MajorTickLabels = {''};
            app.CameraPanRightLeftSlider.ValueChangingFcn = createCallbackFcn(app, @CameraPanRightLeftSliderValueChanging, true);
            app.CameraPanUpDownSlider = uislider(app.GridLayout2);
            app.CameraPanUpDownSlider.Limits = [-1 1];
            app.CameraPanUpDownSlider.MajorTickLabels = {''};
            app.CameraPanUpDownSlider.ValueChangingFcn = createCallbackFcn(app, @CameraPanUpDownSliderValueChanging, true);
            app.CameraMoveForwardsBackwardsPanel = uipanel(app.GridLayout);
            app.CameraMoveForwardsBackwardsPanel.Title = 'Camera Move Forwards/Backwards';
            app.GridLayout3 = uigridlayout(app.CameraMoveForwardsBackwardsPanel);
            app.CameraPanInOutSlider = uislider(app.GridLayout3);
            app.CameraPanInOutSlider.Limits = [-1 1];
            app.CameraPanInOutSlider.MajorTickLabels = {''};
            app.CameraPanInOutSlider.ValueChangingFcn = createCallbackFcn(app, @CameraPanInOutSliderValueChanging, true);
            app.CameraRotatePanel = uipanel(app.GridLayout);
            app.CameraRotatePanel.Title = 'Camera Rotate';
            app.GridLayout4 = uigridlayout(app.CameraRotatePanel);
            app.CameraRotateUpDownSlider = uislider(app.GridLayout4);
            app.CameraRotateUpDownSlider.Limits = [-1 1];
            app.CameraRotateUpDownSlider.MajorTickLabels = {''};
            app.CameraRotateUpDownSlider.ValueChangingFcn = createCallbackFcn(app, @CameraRotateUpDownSliderValueChanging, true);
            app.CameraRotateRightLeftSlider = uislider(app.GridLayout4);
            app.CameraRotateRightLeftSlider.Limits = [-1 1];
            app.CameraRotateRightLeftSlider.MajorTicks = [-1 -0.6 -0.2 0.2 0.6 1];
            app.CameraRotateRightLeftSlider.MajorTickLabels = {''};
            app.CameraRotateRightLeftSlider.ValueChangingFcn = createCallbackFcn(app, @CameraRotateRightLeftSliderValueChanging, true);
            app.CameraZoomPanel = uipanel(app.GridLayout);
            app.CameraZoomPanel.Title = 'Camera Zoom';
            app.GridLayout5 = uigridlayout(app.CameraZoomPanel);
            app.CameraZoomSlider = uislider(app.GridLayout5);
            app.CameraZoomSlider.Limits = [0.9 1.1];
            app.CameraZoomSlider.MajorTickLabels = {''};
            app.CameraZoomSlider.ValueChangingFcn = createCallbackFcn(app, @CameraZoomSliderValueChanging, true);
            app.CameraZoomSlider.Value = 1;
            % Show the figure after all components are created
            app.CameraControlsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_AdjustCameraGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CameraControlsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.CameraControlsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CameraControlsUIFigure)
        end
    end
end