classdef lvd_viewSettingsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_viewSettingsGUI             matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        saveAndCloseButton              matlab.ui.control.Button
        uipanel1                        matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        ViewProfileDetailsTab           matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        UserInterfacePanel              matlab.ui.container.Panel
        GridLayout36                    matlab.ui.container.GridLayout
        Render2DGroundTrackCheckBox     matlab.ui.control.CheckBox
        UseEventColorsinScriptListCheckBox  matlab.ui.control.CheckBox
        EventsPanel                     matlab.ui.container.Panel
        GridLayout35                    matlab.ui.container.GridLayout
        EventsToPlotListbox             matlab.ui.control.ListBox
        PlotAllEventsCheckBox           matlab.ui.control.CheckBox
        uipanel4                        matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        updateViewAxesCheckbox          matlab.ui.control.CheckBox
        setAsActiveProfileCheckbox      matlab.ui.control.CheckBox
        rendererCombo                   matlab.ui.control.DropDown
        text24                          matlab.ui.control.Label
        profileDesc                     matlab.ui.control.TextArea
        text4                           matlab.ui.control.Label
        nameText                        matlab.ui.control.EditField
        text3                           matlab.ui.control.Label
        ViewAxesOptionsTab              matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        GridLayout39                    matlab.ui.container.GridLayout
        SkyboxPanel                     matlab.ui.container.Panel
        GridLayout40                    matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout42                    matlab.ui.container.GridLayout
        SkyboxPreviewImage              matlab.ui.control.Image
        GridLayout41                    matlab.ui.container.GridLayout
        SizeMultiplierEditField         matlab.ui.control.NumericEditField
        SizeMultiplierEditFieldLabel    matlab.ui.control.Label
        SkyboxFileDropDownMenu          matlab.ui.control.DropDown
        EnableSkyboxin3DViewCheckBox    matlab.ui.control.CheckBox
        GroundTrackDisplayPanel         matlab.ui.container.Panel
        GridLayout37                    matlab.ui.container.GridLayout
        GridLayout38                    matlab.ui.container.GridLayout
        NumTerrainContourLevelsSpinner  matlab.ui.control.Spinner
        NumContourLevelsLabel           matlab.ui.control.Label
        ShowTerrainContoursCheckBox     matlab.ui.control.CheckBox
        GridLayout14                    matlab.ui.container.GridLayout
        uipanel12                       matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        showSunLightingCheckbox         matlab.ui.control.CheckBox
        showSunVectorCheckbox           matlab.ui.control.CheckBox
        uipanel5                        matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        ShowAxesBoxCheckBox             matlab.ui.control.CheckBox
        GridLayout17                    matlab.ui.container.GridLayout
        text10                          matlab.ui.control.Label
        gridAlphaText                   matlab.ui.control.EditField
        text9                           matlab.ui.control.Label
        minorGridLinesColorCombo        matlab.ui.control.DropDown
        text7                           matlab.ui.control.Label
        majorGridLinesColorCombo        matlab.ui.control.DropDown
        text6                           matlab.ui.control.Label
        gridTypeCombo                   matlab.ui.control.DropDown
        text8                           matlab.ui.control.Label
        axesBackgroundColorCombo        matlab.ui.control.DropDown
        text5                           matlab.ui.control.Label
        UseThemeforAxesColorsCheckBox   matlab.ui.control.CheckBox
        GridLayout9                     matlab.ui.container.GridLayout
        ProjectionTypePanel             matlab.ui.container.Panel
        GridLayout33                    matlab.ui.container.GridLayout
        ViewProjCombo                   matlab.ui.control.DropDown
        uipanel10                       matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        displayZAxisCheckbox            matlab.ui.control.CheckBox
        displayYAxisCheckbox            matlab.ui.control.CheckBox
        displayXAxisCheckbox            matlab.ui.control.CheckBox
        uipanel6                        matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        showFixedFrameGridCheckbox      matlab.ui.control.CheckBox
        showSoICheckbox                 matlab.ui.control.CheckBox
        uipanel7                        matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        GridLayout11                    matlab.ui.container.GridLayout
        setFrameOptionsButton           matlab.ui.control.Button
        trajViewFrameCombo              matlab.ui.control.DropDown
        text12                          matlab.ui.control.Label
        evtsPlottingStyleCombo          matlab.ui.control.DropDown
        text11                          matlab.ui.control.Label
        SpacecraftCeletialBodiesTab     matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        GridLayout34                    matlab.ui.container.GridLayout
        uipanel11_2                     matlab.ui.container.Panel
        GridLayout24_2                  matlab.ui.container.GridLayout
        srpVectIncrText                 matlab.ui.control.EditField
        text19_2                        matlab.ui.control.Label
        GridLayout26_2                  matlab.ui.container.GridLayout
        text20_2                        matlab.ui.control.Label
        srpVectScaleText                matlab.ui.control.EditField
        text18_2                        matlab.ui.control.Label
        GridLayout25_2                  matlab.ui.container.GridLayout
        srpVectLineStyleCombo           matlab.ui.control.DropDown
        srpVectColorCombo               matlab.ui.control.DropDown
        showSrpVectorsCheckbox          matlab.ui.control.CheckBox
        uipanel11_3                     matlab.ui.container.Panel
        GridLayout24_3                  matlab.ui.container.GridLayout
        dragVectIncrText                matlab.ui.control.EditField
        text19_3                        matlab.ui.control.Label
        GridLayout26_3                  matlab.ui.container.GridLayout
        text20_3                        matlab.ui.control.Label
        dragVectScaleText               matlab.ui.control.EditField
        text18_3                        matlab.ui.control.Label
        GridLayout25_3                  matlab.ui.container.GridLayout
        dragVectLineStyleCombo          matlab.ui.control.DropDown
        dragVectColorCombo              matlab.ui.control.DropDown
        showDragVectorsCheckbox         matlab.ui.control.CheckBox
        GridLayout21                    matlab.ui.container.GridLayout
        CelestialGroundTrackDisplayPanel  matlab.ui.container.Panel
        GridLayout43                    matlab.ui.container.GridLayout
        ShowCelestialBodiesonGroundTrackCheckBox  matlab.ui.control.CheckBox
        uipanel11                       matlab.ui.container.Panel
        GridLayout24                    matlab.ui.container.GridLayout
        thrustVectIncrText              matlab.ui.control.EditField
        text19                          matlab.ui.control.Label
        GridLayout26                    matlab.ui.container.GridLayout
        text20                          matlab.ui.control.Label
        thrustVectScaleText             matlab.ui.control.EditField
        text18                          matlab.ui.control.Label
        GridLayout25                    matlab.ui.container.GridLayout
        thrustVectLineStyleCombo        matlab.ui.control.DropDown
        thrustVectColorCombo            matlab.ui.control.DropDown
        showThrustVectorsCheckbox       matlab.ui.control.CheckBox
        uipanel13                       matlab.ui.container.Panel
        GridLayout22                    matlab.ui.container.GridLayout
        GridLayout23                    matlab.ui.container.GridLayout
        text22                          matlab.ui.control.Label
        scBodyAxesScaleText             matlab.ui.control.EditField
        text21                          matlab.ui.control.Label
        showScBodyAxesCheckbox          matlab.ui.control.CheckBox
        uipanel9                        matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        showCbAtmo                      matlab.ui.control.CheckBox
        GridLayout20                    matlab.ui.container.GridLayout
        text26                          matlab.ui.control.Label
        meshEdgeAlphaText               matlab.ui.control.EditField
        text25                          matlab.ui.control.Label
        bodyPlottingStyleCombo          matlab.ui.control.DropDown
        text15                          matlab.ui.control.Label
        bodiesToPlotListbox             matlab.ui.control.ListBox
        GroundObjectsTab                matlab.ui.container.Tab
        GridLayout27                    matlab.ui.container.GridLayout
        GridLayout44                    matlab.ui.container.GridLayout
        GroundTrackDisplayPanel_2       matlab.ui.container.Panel
        GridLayout43_2                  matlab.ui.container.GridLayout
        ShowGroundObjsonGroundTrackCheckBox  matlab.ui.control.CheckBox
        uipanel14                       matlab.ui.container.Panel
        GridLayout28                    matlab.ui.container.GridLayout
        showLoSToScCheckbox             matlab.ui.control.CheckBox
        showGrdObjTrksCheckbox          matlab.ui.control.CheckBox
        grdObjsListbox                  matlab.ui.control.ListBox
        GeometryTab                     matlab.ui.container.Tab
        GridLayout29                    matlab.ui.container.GridLayout
        uipanel15                       matlab.ui.container.Panel
        GridLayout30                    matlab.ui.container.GridLayout
        GeometricPointsGroundTrackDisplayPanel  matlab.ui.container.Panel
        GridLayout43_3                  matlab.ui.container.GridLayout
        ShowGeometricPointsonGroundTrackCheckBox  matlab.ui.control.CheckBox
        refFramessToPlotListbox         matlab.ui.control.ListBox
        text29                          matlab.ui.control.Label
        planesToPlotListbox             matlab.ui.control.ListBox
        vectorsToPlotListbox            matlab.ui.control.ListBox
        text31                          matlab.ui.control.Label
        text28                          matlab.ui.control.Label
        anglesToPlotListbox             matlab.ui.control.ListBox
        pointsToPlotListbox             matlab.ui.control.ListBox
        text30                          matlab.ui.control.Label
        text27                          matlab.ui.control.Label
        SensorsTab                      matlab.ui.container.Tab
        GridLayout31                    matlab.ui.container.GridLayout
        uipanel15_2                     matlab.ui.container.Panel
        GridLayout32                    matlab.ui.container.GridLayout
        sensorTgtsToPlotListbox         matlab.ui.control.ListBox
        sensorsToPlotListbox            matlab.ui.control.ListBox
        text30_2                        matlab.ui.control.Label
        text27_2                        matlab.ui.control.Label
        uipanel2                        matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        removeViewProfileButton         matlab.ui.control.Button
        addViewProfileButton            matlab.ui.control.Button
        GridLayout5                     matlab.ui.container.GridLayout
        moveViewProfileUpButton         matlab.ui.control.Button
        moveViewProfileDownButton       matlab.ui.control.Button
        viewProfilesListbox             matlab.ui.control.ListBox
        text2                           matlab.ui.control.Label
    end


    methods (Access = private)
        function profile = getSelectedProfile(~, handles)
            viewSettings = getappdata(handles.lvd_viewSettingsGUI,'viewSettings');

            ind = handles.viewProfilesListbox.Value;
            profile = viewSettings.getProfileAtInd(ind);
        end

        function handles = populateGUI(app, viewSettings, handles)
            handles.viewProfilesListbox.String = viewSettings.getListboxStr();
            handles.viewProfilesListbox.Value = viewSettings.getIndOfSelectedProfile();

            lvdData = viewSettings.lvdData;
            
            celBodyData = lvdData.celBodyData;
            populateBodiesCombo(celBodyData, handles.bodiesToPlotListbox);

            grdObjSet = lvdData.groundObjs;
            handles.grdObjsListbox.String = grdObjSet.getListboxStr();

            geometry = lvdData.geometry;
            [~, plotPointsListBoxStr] = geometry.points.getPlottablePoints();
            handles.pointsToPlotListbox.String = plotPointsListBoxStr;
            handles.vectorsToPlotListbox.String = geometry.vectors.getListboxStr();
            handles.refFramessToPlotListbox.String = geometry.refFrames.getListboxStr();
            handles.anglesToPlotListbox.String = geometry.angles.getListboxStr();
            handles.planesToPlotListbox.String = geometry.planes.getListboxStr();

            handles.sensorsToPlotListbox.String = lvdData.sensors.getListboxStr();
            handles.sensorTgtsToPlotListbox.String = lvdData.sensorTgts.getListboxStr();

            profile = getSelectedProfile(app, handles);
            updateGuiForProfile(app, profile, handles);
            setDeleteButtonEnable(app, handles);
        end

        function setDeleteButtonEnable(~, handles)
            viewSettings = getappdata(handles.lvd_viewSettingsGUI,'viewSettings');
            numProfiles = viewSettings.getNumProfiles();

            if(numProfiles > 1)
                handles.removeViewProfileButton.Enable = 'on';
            else
                handles.removeViewProfileButton.Enable = 'off';
            end
        end

        function updateGuiForProfile(app, profile, handles)
            arguments
                app(1,1) lvd_viewSettingsGUI_App
                profile(1,1) LaunchVehicleViewProfile
                handles
            end

            viewSettings = getappdata(handles.lvd_viewSettingsGUI,'viewSettings');
            lvdData = viewSettings.lvdData;
            celBodyData = lvdData.celBodyData;

            handles.nameText.String = profile.name;
            handles.profileDesc.String = profile.desc;
            handles.setAsActiveProfileCheckbox.Value = viewSettings.isProfileActive(profile);

            %events
            app.PlotAllEventsCheckBox.Value = profile.plotAllEvents;

            %ui
            app.UseEventColorsinScriptListCheckBox.Value = profile.scriptBoxUseEventColors;
            app.Render2DGroundTrackCheckBox.Value = profile.showGrdTrk;

            [listboxStr, events] = lvdData.script.getListboxStr();
            app.EventsToPlotListbox.Items = listboxStr;
            app.EventsToPlotListbox.ItemsData = events;
            app.EventsToPlotListbox.Value = profile.eventsToPlot;

            if(app.PlotAllEventsCheckBox.Value)
                app.EventsToPlotListbox.Enable = "off";
            else
                app.EventsToPlotListbox.Enable = "on";
            end

            %view axes settings
            app.UseThemeforAxesColorsCheckBox.Value = profile.useThemeForAxes;
            app.setAxesPropertiesForUseThemeCheck();

            handles.showSoICheckbox.Value = profile.showSoIRadius;
            handles.showFixedFrameGridCheckbox.Value = profile.showLongLatAnnotations;

            lvdSetupColorPicker(app.lvd_viewSettingsGUI, app.GridLayout16, 3, 1, handles.axesBackgroundColorCombo, lvd_colorSpecToRGB(profile.backgroundColor), 'Pick a color.', 'backgroundColor', @(rgb) set(profile, 'backgroundColor', rgb));

            lvdSetupColorPicker(app.lvd_viewSettingsGUI, app.GridLayout16, 7, 1, handles.majorGridLinesColorCombo, lvd_colorSpecToRGB(profile.majorGridColor), 'Pick a color.', 'majorGridColor', @(rgb) set(profile, 'majorGridColor', rgb));

            handles.gridTypeCombo.String = ViewGridTypeEnum.getListBoxStr();
            handles.gridTypeCombo.Value = ViewGridTypeEnum.getIndForName(profile.gridType.name);

            lvdSetupColorPicker(app.lvd_viewSettingsGUI, app.GridLayout16, 9, 1, handles.minorGridLinesColorCombo, lvd_colorSpecToRGB(profile.minorGridColor), 'Pick a color.', 'minorGridColor', @(rgb) set(profile, 'minorGridColor', rgb));
            app.setAxesPropertiesForUseThemeCheck();

            handles.gridAlphaText.String = fullAccNum2Str(100*profile.gridTransparency);

            handles.evtsPlottingStyleCombo.String = ViewEventsTypeEnum.getListBoxStr();
            handles.evtsPlottingStyleCombo.Value = ViewEventsTypeEnum.getIndForName(profile.trajEvtsViewType.name);

            handles.trajViewFrameCombo.String = ReferenceFrameEnum.getListBoxStr();
            handles.trajViewFrameCombo.Value = ReferenceFrameEnum.getIndForName(profile.frame.typeEnum.name);
            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', profile.frame.getNameStr());

            [~, sortedBodyInfo] = ma_getSortedBodyNames(celBodyData);

            indsToSel = [];
            for(i=1:length(profile.bodiesToPlot)) %#ok<*NO4LP> 
                tf = cellfun(@(c) c == profile.bodiesToPlot(i), sortedBodyInfo);
                indsToSel(end+1) = find(tf,1,'first'); %#ok<AGROW>
            end
            handles.bodiesToPlotListbox.Value = indsToSel;

            handles.bodyPlottingStyleCombo.String = ViewProfileBodyPlottingStyle.getListBoxStr();
            handles.bodyPlottingStyleCombo.Value = ViewProfileBodyPlottingStyle.getIndForName(profile.bodyPlotStyle.name);

            handles.displayXAxisCheckbox.Value = double(profile.dispXAxis);
            handles.displayYAxisCheckbox.Value = double(profile.dispYAxis);
            handles.displayZAxisCheckbox.Value = double(profile.dispZAxis);

            app.ShowAxesBoxCheckBox.Value = logical(profile.showAxesBox);

            %Ground Track Display
            app.ShowCelestialBodiesonGroundTrackCheckBox.Value = logical(profile.showCelestialBodyGrdTracks);
            app.ShowGroundObjsonGroundTrackCheckBox.Value = logical(profile.showGroundObjsGrdTracks);
            app.ShowGeometricPointsonGroundTrackCheckBox.Value = logical(profile.showGeomPointsGrdTracks);

            app.ShowTerrainContoursCheckBox.Value = logical(profile.showTerrainContours);
            app.NumTerrainContourLevelsSpinner.Value = double(profile.numTerrainContourLevels);

            %Skybox
            app.EnableSkyboxin3DViewCheckBox.Value = profile.useSkybox;
            app.SkyboxFileDropDownMenu.Value = profile.skyBoxImgFileName;
            app.SizeMultiplierEditField.Value = profile.skyboxRadiusMultiplier;
            app.SkyboxPreviewImage.ImageSource = profile.skyBoxImgFileName;

            if(profile.useSkybox)
                app.SkyboxFileDropDownMenu.Enable = "on";
                app.SizeMultiplierEditField.Enable = "on";
                app.SkyboxPreviewImage.Enable = "on";
            else
                app.SkyboxFileDropDownMenu.Enable = "off";
                app.SizeMultiplierEditField.Enable = "off";
                app.SkyboxPreviewImage.Enable = "off";
            end

            %show thrust vectors
            handles.showThrustVectorsCheckbox.Value = double(profile.showThrustVectors);

            lvdSetupColorPicker(app.lvd_viewSettingsGUI, app.GridLayout25, 1, 1, handles.thrustVectColorCombo, lvd_colorSpecToRGB(profile.thrustVectColor), 'The color of the thrust vectors drawn on the trajectory.', 'thrustVectColor', @(rgb) set(profile, 'thrustVectColor', rgb));

            handles.thrustVectLineStyleCombo.String = LineSpecEnum.getListboxStr();
            handles.thrustVectLineStyleCombo.Value = LineSpecEnum.getIndForName(profile.thrustVectLineType.name);

            handles.thrustVectScaleText.String = fullAccNum2Str(profile.thrustVectScale);
            handles.thrustVectIncrText.String = fullAccNum2Str(profile.thrustVectEntryIncr);

            %show drag vectors
            app.showDragVectorsCheckbox.Value = profile.showDragVectors;

            lvdSetupColorPicker(app.lvd_viewSettingsGUI, app.GridLayout25_3, 1, 1, app.dragVectColorCombo, lvd_colorSpecToRGB(profile.dragVectColor), 'The color of the drag vectors drawn on the trajectory.', 'dragVectColor', @(rgb) set(profile, 'dragVectColor', rgb));

            [listBoxStr, m] = LineSpecEnum.getListboxStr();
            app.dragVectLineStyleCombo.Items = listBoxStr;
            app.dragVectLineStyleCombo.ItemsData = m;
            app.dragVectLineStyleCombo.Value = profile.dragVectLineType;

            app.dragVectScaleText.Value = fullAccNum2Str(profile.dragVectScale);
            app.dragVectIncrText.Value = fullAccNum2Str(profile.dragVectEntryIncr);

            %show srp vectors
            app.showSrpVectorsCheckbox.Value = profile.showSrpVectors;

            lvdSetupColorPicker(app.lvd_viewSettingsGUI, app.GridLayout25_2, 1, 1, app.srpVectColorCombo, lvd_colorSpecToRGB(profile.srpVectColor), 'The color of the SRP vectors drawn on the trajectory.', 'srpVectColor', @(rgb) set(profile, 'srpVectColor', rgb));

            [listBoxStr, m] = LineSpecEnum.getListboxStr();
            app.srpVectLineStyleCombo.Items = listBoxStr;
            app.srpVectLineStyleCombo.ItemsData = m;
            app.srpVectLineStyleCombo.Value = profile.srpVectLineType;

            app.srpVectScaleText.Value = fullAccNum2Str(profile.srpVectScale);
            app.srpVectIncrText.Value = fullAccNum2Str(profile.srpVectEntryIncr);

            %show sun lighting
            handles.showSunLightingCheckbox.Value = double(profile.showLighting);
            handles.showSunVectorCheckbox.Value = double(profile.showSunVect);

            [listboxStr, m] = ViewProjectionTypeEnum.getListBoxStr();
            app.ViewProjCombo.Items = listboxStr;
            app.ViewProjCombo.ItemsData = m;
            app.ViewProjCombo.Value = profile.projType;   

            handles.showScBodyAxesCheckbox.Value = double(profile.showScBodyAxes);
            handles.scBodyAxesScaleText.String = fullAccNum2Str(profile.scBodyAxesScale);

            grdObjSet = viewSettings.lvdData.groundObjs;
            handles.grdObjsListbox.Value = grdObjSet.getIndsForGroundObjs(profile.groundObjsToPlot);
            handles.showGrdObjTrksCheckbox.Value = double(profile.showGndTracks);
            handles.showLoSToScCheckbox.Value = double(profile.showGrdObjLoS);

            handles.rendererCombo.String = FigureRendererEnum.getListboxStr();
            handles.rendererCombo.Value = FigureRendererEnum.getIndForName(profile.renderer.name);

            handles.meshEdgeAlphaText.String = fullAccNum2Str(100*profile.meshEdgeAlpha);

            handles.updateViewAxesCheckbox.Value = double(profile.updateViewAxesLimits);

            handles.showCbAtmo.Value = double(profile.showAtmosphere);

            geometry = viewSettings.lvdData.geometry;
            [plotPoints, ~] = geometry.points.getPlottablePoints();
            handles.pointsToPlotListbox.Value = find(ismember(plotPoints, profile.pointsToPlot));
            handles.vectorsToPlotListbox.Value = geometry.vectors.getIndsForVectors(profile.vectorsToPlot);
            handles.refFramessToPlotListbox.Value = geometry.refFrames.getIndsForRefFrames(profile.refFramesToPlot);
            handles.anglesToPlotListbox.Value = geometry.angles.getIndsForAngles(profile.anglesToPlot);
            handles.planesToPlotListbox.Value = geometry.planes.getIndsForPlanes(profile.planesToPlot);

            handles.sensorsToPlotListbox.Value = viewSettings.lvdData.sensors.getIndsForSensors(profile.sensorsToPlot);
            handles.sensorTgtsToPlotListbox.Value = viewSettings.lvdData.sensorTgts.getIndsForTarget(profile.sensorTgtsToPlot);
            
            setDeleteButtonEnable(app, handles);
        end

        
        function setAxesPropertiesForUseThemeCheck(app)
            if(app.UseThemeforAxesColorsCheckBox.Value)
                lvdSetColorPickerEnable(app.lvd_viewSettingsGUI, 'backgroundColor', 'off');
                lvdSetColorPickerEnable(app.lvd_viewSettingsGUI, 'majorGridColor', 'off');
                lvdSetColorPickerEnable(app.lvd_viewSettingsGUI, 'minorGridColor', 'off');
            else
                lvdSetColorPickerEnable(app.lvd_viewSettingsGUI, 'backgroundColor', 'on');
                lvdSetColorPickerEnable(app.lvd_viewSettingsGUI, 'majorGridColor', 'on');
                lvdSetColorPickerEnable(app.lvd_viewSettingsGUI, 'minorGridColor', 'on');
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_viewSettingsGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_viewSettingsGUI (see VARARGIN)

            % Choose default command line output for lvd_viewSettingsGUI
            handles.output = hObject;
            
            centerUIFigure(app.lvd_viewSettingsGUI);
            applySelectedThemeToApp(app);

            viewSettings = varargin{1};
            setappdata(hObject,'viewSettings',viewSettings);

            handles = populateGUI(app, viewSettings, handles);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_viewSettingsGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_viewSettingsGUI);
        end

        % Button pushed function: addViewProfileButton
        function addViewProfileButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addViewProfileButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            viewSettings = getappdata(handles.lvd_viewSettingsGUI,'viewSettings');
            newProfile = LaunchVehicleViewProfile();
            %     newProfile.frame = BodyCenteredInertialFrame(viewSettings.lvdData.initialState.centralBody, viewSettings.lvdData.celBodyData);
            newProfile.frame = viewSettings.lvdData.initialState.centralBody.getBodyCenteredInertialFrame();
            viewSettings.addViewProfile(newProfile);

            handles.viewProfilesListbox.String = viewSettings.getListboxStr();

            handles.viewProfilesListbox.Value = viewSettings.getNumProfiles();
            updateGuiForProfile(app, newProfile, handles);
            setDeleteButtonEnable(app, handles);
        end

        % Value changed function: anglesToPlotListbox
        function anglesToPlotListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to anglesToPlotListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns anglesToPlotListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from anglesToPlotListbox
            viewSettings = getappdata(handles.lvd_viewSettingsGUI,'viewSettings');
            angleSet = viewSettings.lvdData.geometry.angles;

            profile = getSelectedProfile(app, handles);

            inds = hObject.Value;
            if(not(isempty(inds)))
                profile.anglesToPlot = angleSet.getAnglesForInds(inds);
            else
                profile.anglesToPlot = AbstractGeometricAngle.empty(1,0);
            end
        end

        % Value changed function: axesBackgroundColorCombo
        function axesBackgroundColorCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to axesBackgroundColorCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns axesBackgroundColorCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from axesBackgroundColorCombo
            profile = getSelectedProfile(app, handles);

            contents = cellstr(get(hObject,'String'));
            str = contents{get(hObject,'Value')};

            profile.backgroundColor = ColorSpecEnum.getEnumForListboxStr(str);
        end

        % Value changed function: bodiesToPlotListbox
        function bodiesToPlotListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to bodiesToPlotListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns bodiesToPlotListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from bodiesToPlotListbox
            viewSettings = getappdata(handles.lvd_viewSettingsGUI,'viewSettings');
            celBodyData = viewSettings.lvdData.celBodyData;
            [~, sortedBodyInfo] = ma_getSortedBodyNames(celBodyData);

            profile = getSelectedProfile(app, handles);

            inds = hObject.Value;
            if(not(isempty(inds)))
                profile.bodiesToPlot = [sortedBodyInfo{inds}];
            else
                profile.bodiesToPlot = KSPTOT_BodyInfo.empty(1,0);
            end
        end

        % Value changed function: bodyPlottingStyleCombo
        function bodyPlottingStyleCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to bodyPlottingStyleCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns bodyPlottingStyleCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from bodyPlottingStyleCombo
            profile = getSelectedProfile(app, handles);

            contents = cellstr(get(hObject,'String'));
            str = contents{get(hObject,'Value')};

            profile.bodyPlotStyle = ViewProfileBodyPlottingStyle.getEnumForListboxStr(str);
        end

        % Value changed function: displayXAxisCheckbox
        function displayXAxisCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to displayXAxisCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of displayXAxisCheckbox
            profile = getSelectedProfile(app, handles);
            profile.dispXAxis = logical(get(hObject,'Value'));
        end

        % Value changed function: displayYAxisCheckbox
        function displayYAxisCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to displayYAxisCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of displayYAxisCheckbox
            profile = getSelectedProfile(app, handles);
            profile.dispYAxis = logical(get(hObject,'Value'));
        end

        % Value changed function: displayZAxisCheckbox
        function displayZAxisCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to displayZAxisCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of displayZAxisCheckbox
            profile = getSelectedProfile(app, handles);
            profile.dispZAxis = logical(get(hObject,'Value'));
        end

        % Value changed function: evtsPlottingStyleCombo
        function evtsPlottingStyleCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to evtsPlottingStyleCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns evtsPlottingStyleCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from evtsPlottingStyleCombo
            profile = getSelectedProfile(app, handles);

            contents = cellstr(get(hObject,'String'));
            str = contents{get(hObject,'Value')};

            profile.trajEvtsViewType = ViewEventsTypeEnum.getEnumForListboxStr(str);
        end

        % Value changed function: grdObjsListbox
        function grdObjsListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to grdObjsListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns grdObjsListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from grdObjsListbox
            viewSettings = getappdata(handles.lvd_viewSettingsGUI,'viewSettings');
            grdObjs = viewSettings.lvdData.groundObjs;

            profile = getSelectedProfile(app, handles);

            inds = hObject.Value;
            if(not(isempty(inds)))
                profile.groundObjsToPlot = grdObjs.getGroundObjsForInds(inds);
            else
                profile.groundObjsToPlot = LaunchVehicleGroundObject.empty(1,0);
            end
        end

        % Value changed function: gridAlphaText
        function gridAlphaText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to gridAlphaText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of gridAlphaText as text
            %        str2double(get(hObject,'String')) returns contents of gridAlphaText as a double
            hObject.String = attemptStrEval(hObject.String);

            profile = getSelectedProfile(app, handles);

            errMsg = {};

            alpha = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Grid Transparency';
            lb = 0;
            ub = 100;
            isInt = false;
            errMsg = validateNumber(alpha, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                profile.gridTransparency = alpha/100;
            else
                hObject.String = fullAccNum2Str(profile.gridTransparency);

                msgbox(errMsg,'Invalid Grid Transparency Value','error');
            end
        end

        % Value changed function: gridTypeCombo
        function gridTypeCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to gridTypeCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns gridTypeCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from gridTypeCombo
            profile = getSelectedProfile(app, handles);

            contents = cellstr(get(hObject,'String'));
            str = contents{get(hObject,'Value')};

            profile.gridType = ViewGridTypeEnum.getEnumForListboxStr(str);
        end

        % Window key press function: lvd_viewSettingsGUI
        function lvd_viewSettingsGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_viewSettingsGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_viewSettingsGUI);
            end
        end

        % Value changed function: majorGridLinesColorCombo
        function majorGridLinesColorCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to majorGridLinesColorCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns majorGridLinesColorCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from majorGridLinesColorCombo
            profile = getSelectedProfile(app, handles);

            contents = cellstr(get(hObject,'String'));
            str = contents{get(hObject,'Value')};

            profile.majorGridColor = ColorSpecEnum.getEnumForListboxStr(str);
        end

        % Value changed function: meshEdgeAlphaText
        function meshEdgeAlphaText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to meshEdgeAlphaText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of meshEdgeAlphaText as text
            %        str2double(get(hObject,'String')) returns contents of meshEdgeAlphaText as a double
            hObject.String = attemptStrEval(hObject.String);

            profile = getSelectedProfile(app, handles);

            errMsg = {};

            alpha = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Mesh Edge Transparency';
            lb = 0;
            ub = 100;
            isInt = false;
            errMsg = validateNumber(alpha, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                profile.meshEdgeAlpha = alpha/100;
            else
                hObject.String = fullAccNum2Str(profile.meshEdgeAlpha);

                msgbox(errMsg,'Invalid Mesh Edge Transparency Value','error');
            end
        end

        % Value changed function: minorGridLinesColorCombo
        function minorGridLinesColorCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to minorGridLinesColorCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns minorGridLinesColorCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from minorGridLinesColorCombo
            profile = getSelectedProfile(app, handles);

            contents = cellstr(get(hObject,'String'));
            str = contents{get(hObject,'Value')};

            profile.minorGridColor = ColorSpecEnum.getEnumForListboxStr(str);
        end

        % Button pushed function: moveViewProfileDownButton
        function moveViewProfileDownButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to moveViewProfileDownButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            viewSettings = getappdata(handles.lvd_viewSettingsGUI,'viewSettings');

            profileNum = get(handles.viewProfilesListbox,'Value');
            viewSettings.moveProfileAtIndexDown(profileNum);

            if(profileNum < viewSettings.getNumProfiles())
                set(handles.viewProfilesListbox,'Value',profileNum+1);
            end

            handles.viewProfilesListbox.String = viewSettings.getListboxStr();
        end

        % Button pushed function: moveViewProfileUpButton
        function moveViewProfileUpButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to moveViewProfileUpButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            viewSettings = getappdata(handles.lvd_viewSettingsGUI,'viewSettings');

            profileNum = get(handles.viewProfilesListbox,'Value');
            viewSettings.moveProfileAtIndexUp(profileNum);

            if(profileNum > 1)
                set(handles.viewProfilesListbox,'Value',profileNum-1);
            end

            handles.viewProfilesListbox.String = viewSettings.getListboxStr();
        end

        % Value changed function: nameText
        function nameText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to nameText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of nameText as text
            %        str2double(get(hObject,'String')) returns contents of nameText as a double
            profile = getSelectedProfile(app, handles);
            profile.name = get(hObject,'String');

            viewSettings = getappdata(handles.lvd_viewSettingsGUI,'viewSettings');
            handles.viewProfilesListbox.String = viewSettings.getListboxStr();
        end

        % Value changed function: planesToPlotListbox
        function planesToPlotListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to planesToPlotListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns planesToPlotListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from planesToPlotListbox
            viewSettings = getappdata(handles.lvd_viewSettingsGUI,'viewSettings');
            planeSet = viewSettings.lvdData.geometry.planes;

            profile = getSelectedProfile(app, handles);

            inds = hObject.Value;
            if(not(isempty(inds)))
                profile.planesToPlot = planeSet.getPlanesForInds(inds);
            else
                profile.planesToPlot = AbstractGeometricPlane.empty(1,0);
            end
        end

        % Value changed function: pointsToPlotListbox
        function pointsToPlotListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pointsToPlotListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns pointsToPlotListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from pointsToPlotListbox
            viewSettings = getappdata(handles.lvd_viewSettingsGUI,'viewSettings');
            pointSet = viewSettings.lvdData.geometry.points;

            profile = getSelectedProfile(app, handles);

            inds = hObject.Value;
            if(not(isempty(inds)))
                [plotPoints, ~] = pointSet.getPlottablePoints();
                profile.pointsToPlot = plotPoints(inds);
            else
                profile.pointsToPlot = AbstractGeometricPoint.empty(1,0);
            end
        end

        % Value changed function: profileDesc
        function profileDesc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to profileDesc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of profileDesc as text
            %        str2double(get(hObject,'String')) returns contents of profileDesc as a double
            profile = getSelectedProfile(app, handles);
            profile.desc = get(hObject,'String');
        end

        % Value changed function: refFramessToPlotListbox
        function refFramessToPlotListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to refFramessToPlotListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns refFramessToPlotListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from refFramessToPlotListbox
            viewSettings = getappdata(handles.lvd_viewSettingsGUI,'viewSettings');
            refFrameSet = viewSettings.lvdData.geometry.refFrames;

            profile = getSelectedProfile(app, handles);

            inds = hObject.Value;
            if(not(isempty(inds)))
                profile.refFramesToPlot = refFrameSet.getRefFramesForInds(inds);
            else
                profile.refFramesToPlot = AbstractGeometricRefFrame.empty(1,0);
            end
        end

        % Button pushed function: removeViewProfileButton
        function removeViewProfileButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeViewProfileButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            viewSettings = getappdata(handles.lvd_viewSettingsGUI,'viewSettings');

            profile = getSelectedProfile(app, handles);
            isActiveProfile = viewSettings.isProfileActive(profile);
            viewSettings.removeViewProfile(profile);
            numProfiles = viewSettings.getNumProfiles();

            app.viewProfilesListbox.Items = viewSettings.getListboxStr();

            if(handles.viewProfilesListbox.Value > numProfiles)
                handles.viewProfilesListbox.Value = numProfiles;
            end

            profile = getSelectedProfile(app, handles);
            if(isActiveProfile)
                viewSettings.setProfileAsActive(profile);
                handles.viewProfilesListbox.String = viewSettings.getListboxStr();
            end

            updateGuiForProfile(app, profile, handles);
            setDeleteButtonEnable(app, handles);
        end

        % Value changed function: rendererCombo
        function rendererCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to rendererCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns rendererCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from rendererCombo
            profile = getSelectedProfile(app, handles);

            contents = cellstr(get(hObject,'String'));
            str = contents{get(hObject,'Value')};

            profile.renderer = FigureRendererEnum.getEnumForListboxStr(str);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
%             hObject = app.saveAndCloseButton;
            
            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = {};
            if(isempty(errMsg))
                close(app.lvd_viewSettingsGUI);
            else
                msgbox(errMsg,'Errors were found while editing LVD view settings.','error');
            end
        end

        % Value changed function: scBodyAxesScaleText
        function scBodyAxesScaleText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to scBodyAxesScaleText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of scBodyAxesScaleText as text
            %        str2double(get(hObject,'String')) returns contents of scBodyAxesScaleText as a double
            hObject.String = attemptStrEval(hObject.String);

            profile = getSelectedProfile(app, handles);

            errMsg = {};

            scale = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'S/C Body Axes Scale';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(scale, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                profile.scBodyAxesScale = scale;
            else
                hObject.String = fullAccNum2Str(profile.scBodyAxesScale);

                msgbox(errMsg,'Invalid S/C Body Axes Scale Value','error');
            end
        end

        % Value changed function: setAsActiveProfileCheckbox
        function setAsActiveProfileCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to setAsActiveProfileCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of setAsActiveProfileCheckbox
            viewSettings = getappdata(handles.lvd_viewSettingsGUI,'viewSettings');
            profile = getSelectedProfile(app, handles);

            viewSettings.setProfileAsActive(profile);

            updateGuiForProfile(app, profile, handles);

            viewSettings = getappdata(handles.lvd_viewSettingsGUI,'viewSettings');
            handles.viewProfilesListbox.String = viewSettings.getListboxStr();
        end

        % Button pushed function: setFrameOptionsButton
        function setFrameOptionsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to setFrameOptionsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            profile = getSelectedProfile(app, handles);
            newFrame = profile.frame.editFrameDialogUI(EditReferenceFrameContextEnum.ForView);

            profile.frame = newFrame;
            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', profile.frame.getNameStr());
        end

        % Value changed function: showCbAtmo
        function showCbAtmo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to showCbAtmo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of showCbAtmo
            profile = getSelectedProfile(app, handles);
            profile.showAtmosphere = logical(get(hObject,'Value'));
        end

        % Value changed function: showFixedFrameGridCheckbox
        function showFixedFrameGridCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to showFixedFrameGridCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of showFixedFrameGridCheckbox
            profile = getSelectedProfile(app, handles);
            profile.showLongLatAnnotations = logical(get(hObject,'Value'));
        end

        % Value changed function: showGrdObjTrksCheckbox
        function showGrdObjTrksCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to showGrdObjTrksCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of showGrdObjTrksCheckbox
            profile = getSelectedProfile(app, handles);
            profile.showGndTracks = logical(get(hObject,'Value'));
        end

        % Value changed function: showLoSToScCheckbox
        function showLoSToScCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to showLoSToScCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of showLoSToScCheckbox
            profile = getSelectedProfile(app, handles);
            profile.showGrdObjLoS = logical(get(hObject,'Value'));
        end

        % Value changed function: showScBodyAxesCheckbox
        function showScBodyAxesCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to showScBodyAxesCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of showScBodyAxesCheckbox
            profile = getSelectedProfile(app, handles);
            profile.showScBodyAxes = logical(get(hObject,'Value'));
        end

        % Value changed function: showSoICheckbox
        function showSoICheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to showSoICheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of showSoICheckbox
            profile = getSelectedProfile(app, handles);
            profile.showSoIRadius = logical(get(hObject,'Value'));
        end

        % Value changed function: showSunLightingCheckbox
        function showSunLightingCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to showSunLightingCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of showSunLightingCheckbox
            profile = getSelectedProfile(app, handles);
            profile.showLighting = logical(get(hObject,'Value'));
        end

        % Value changed function: showSunVectorCheckbox
        function showSunVectorCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to showSunVectorCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of showSunVectorCheckbox
            profile = getSelectedProfile(app, handles);
            profile.showSunVect = logical(get(hObject,'Value'));
        end

        % Value changed function: showThrustVectorsCheckbox
        function showThrustVectorsCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to showThrustVectorsCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of showThrustVectorsCheckbox
            profile = getSelectedProfile(app, handles);
            profile.showThrustVectors = logical(get(hObject,'Value'));
        end

        % Value changed function: thrustVectColorCombo
        function thrustVectColorCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to thrustVectColorCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns thrustVectColorCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from thrustVectColorCombo
            profile = getSelectedProfile(app, handles);

            contents = cellstr(get(hObject,'String'));
            str = contents{get(hObject,'Value')};

            profile.thrustVectColor = ColorSpecEnum.getEnumForListboxStr(str);
        end

        % Value changed function: thrustVectIncrText
        function thrustVectIncrText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to thrustVectIncrText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of thrustVectIncrText as text
            %        str2double(get(hObject,'String')) returns contents of thrustVectIncrText as a double
            hObject.String = attemptStrEval(hObject.String);

            profile = getSelectedProfile(app, handles);

            errMsg = {};

            incr = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Thrust Vector Data Point Increment';
            lb = 1;
            ub = Inf;
            isInt = true;
            errMsg = validateNumber(incr, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                profile.thrustVectEntryIncr = incr;
            else
                hObject.String = fullAccNum2Str(round(profile.thrustVectEntryIncr));

                msgbox(errMsg,'Invalid Thrust Vector Data Point Incrememnt','error');
            end
        end

        % Value changed function: thrustVectLineStyleCombo
        function thrustVectLineStyleCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to thrustVectLineStyleCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns thrustVectLineStyleCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from thrustVectLineStyleCombo
            profile = getSelectedProfile(app, handles);

            contents = cellstr(get(hObject,'String'));
            str = contents{get(hObject,'Value')};

            profile.thrustVectLineType = LineSpecEnum.getEnumForListboxStr(str);
        end

        % Value changed function: thrustVectScaleText
        function thrustVectScaleText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to thrustVectScaleText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of thrustVectScaleText as text
            %        str2double(get(hObject,'String')) returns contents of thrustVectScaleText as a double
            hObject.String = attemptStrEval(hObject.String);

            profile = getSelectedProfile(app, handles);

            errMsg = {};

            scale = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Thrust Vector Scale Factor';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(scale, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                profile.thrustVectScale = scale;
            else
                hObject.String = fullAccNum2Str(profile.thrustVectScale);

                msgbox(errMsg,'Invalid Thrust Vector Scale Factor Value','error');
            end
        end

        % Value changed function: trajViewFrameCombo
        function trajViewFrameCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to trajViewFrameCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns trajViewFrameCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from trajViewFrameCombo
            viewSettings = getappdata(handles.lvd_viewSettingsGUI,'viewSettings');
            lvdData = viewSettings.lvdData;
            celBodyData = lvdData.celBodyData;

            profile = getSelectedProfile(app, handles);
            bodyInfo = profile.frame.getOriginBody();

            contents = cellstr(get(handles.trajViewFrameCombo,'String'));
            selFrameType = contents{get(handles.trajViewFrameCombo,'Value')};
            refFrameEnum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);

            switch refFrameEnum
                case ReferenceFrameEnum.BodyCenteredInertial
                    newFrame = bodyInfo.getBodyCenteredInertialFrame();

                case ReferenceFrameEnum.BodyFixedRotating
                    newFrame = bodyInfo.getBodyFixedFrame();

                case ReferenceFrameEnum.TwoBodyRotating
                    if(not(isempty(bodyInfo.childrenBodyInfo)))
                        primaryBody = bodyInfo;
                        secondaryBody = bodyInfo.childrenBodyInfo(1);
                    else
                        primaryBody = bodyInfo.getParBodyInfo();
                        secondaryBody = bodyInfo;
                    end

                    originPt = TwoBodyRotatingFrameOriginEnum.Primary;

                    newFrame = TwoBodyRotatingFrame(primaryBody, secondaryBody, originPt, celBodyData);

                case ReferenceFrameEnum.UserDefined
                    numFrames = lvdData.geometry.refFrames.getNumRefFrames();
                    if(numFrames >= 1)
                        geometricFrame = AbstractGeometricRefFrame.empty(1,0);
                        for(i=1:numFrames)
                            frame = lvdData.geometry.refFrames.getRefFrameAtInd(i);
                            if(frame.isVehDependent() == false)
                                geometricFrame = frame;
                                break;
                            end
                        end

                        if(not(isempty(geometricFrame)))
                            newFrame = UserDefinedGeometricFrame(geometricFrame, lvdData);
                        else
                            newFrame = bodyInfo.getBodyCenteredInertialFrame();

                            warndlg('There are no geometric frames available which are not dependent on vehicle properties.  A body-centered inertial frame will be selected instead.');
                        end
                    else
                        bodyInfo = getSelectedBodyInfo(handles);
                        newFrame = bodyInfo.getBodyCenteredInertialFrame();

                        warndlg('There are no geometric frames available which are not dependent on vehicle properties.  A body-centered inertial frame will be selected instead.');
                    end

                otherwise
                    error('Unknown reference frame type: %s', string(refFrameEnum));
            end

            if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
                refFrameEnum = newFrame.typeEnum;
                handles.trajViewFrameCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
            end

            profile.frame = newFrame;
            handles.setFrameOptionsButton.TooltipString = sprintf('Current Frame: %s', profile.frame.getNameStr());
        end

        % Value changed function: updateViewAxesCheckbox
        function updateViewAxesCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to updateViewAxesCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of updateViewAxesCheckbox
            profile = getSelectedProfile(app, handles);

            profile.updateViewAxesLimits = logical(get(hObject,'Value'));
        end

        % Value changed function: vectorsToPlotListbox
        function vectorsToPlotListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to vectorsToPlotListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns vectorsToPlotListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from vectorsToPlotListbox
            viewSettings = getappdata(handles.lvd_viewSettingsGUI,'viewSettings');
            vectorSet = viewSettings.lvdData.geometry.vectors;

            profile = getSelectedProfile(app, handles);

            inds = hObject.Value;
            if(not(isempty(inds)))
                profile.vectorsToPlot = vectorSet.getVectorsForInds(inds);
            else
                profile.vectorsToPlot = AbstractGeometricVector.empty(1,0);
            end
        end

        % Value changed function: viewProfilesListbox
        function viewProfilesListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to viewProfilesListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns viewProfilesListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from viewProfilesListbox
            profile = getSelectedProfile(app, handles);
            updateGuiForProfile(app, profile, handles);
        end

        % Close request function: lvd_viewSettingsGUI
        function lvd_viewSettingsGUICloseRequest(app, event)
            close(app.lvd_viewSettingsGUI);
        end

        % Value changed function: sensorsToPlotListbox
        function sensorsToPlotListboxValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            viewSettings = getappdata(handles.lvd_viewSettingsGUI,'viewSettings');
            sensorSet = viewSettings.lvdData.sensors;

            profile = getSelectedProfile(app, handles);

            inds = hObject.Value;
            if(not(isempty(inds)))
                profile.sensorsToPlot = sensorSet.getSensorsForInds(inds);
            else
                profile.sensorsToPlot = AbstractSensor.empty(1,0);
            end
        end

        % Value changed function: sensorTgtsToPlotListbox
        function sensorTgtsToPlotListboxValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            viewSettings = getappdata(handles.lvd_viewSettingsGUI,'viewSettings');
            sensorTgtSet = viewSettings.lvdData.sensorTgts;

            profile = getSelectedProfile(app, handles);

            inds = hObject.Value;
            if(not(isempty(inds)))
                profile.sensorTgtsToPlot = sensorTgtSet.getTargetsForInds(inds);
            else
                profile.sensorTgtsToPlot = AbstractSensorTarget.empty(1,0);
            end
        end

        % Value changed function: ViewProjCombo
        function ViewProjComboValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            profile = getSelectedProfile(app, handles);
            profile.projType = app.ViewProjCombo.Value;
        end

        % Value changed function: showSrpVectorsCheckbox
        function showSrpVectorsCheckboxValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            profile = getSelectedProfile(app, handles);
            profile.showSrpVectors = logical(app.showSrpVectorsCheckbox.Value);
        end

        % Value changed function: srpVectColorCombo
        function srpVectColorComboValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            profile = getSelectedProfile(app, handles);
            profile.srpVectColor = app.srpVectColorCombo.Value;
        end

        % Value changed function: srpVectLineStyleCombo
        function srpVectLineStyleComboValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            profile = getSelectedProfile(app, handles);
            profile.srpVectLineType = app.srpVectLineStyleCombo.Value;
        end

        % Value changed function: srpVectScaleText
        function srpVectScaleTextValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            app.srpVectScaleText.Value = attemptStrEval(app.srpVectScaleText.Value);

            profile = getSelectedProfile(app, handles);

            errMsg = {};

            scale = str2double(app.srpVectScaleText.Value);
            enteredStr = app.srpVectScaleText.Value;
            numberName = 'SRP Force Vector Scale Factor';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(scale, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                profile.srpVectScale = scale;
            else
                app.srpVectScaleText.Value = fullAccNum2Str(profile.srpVectScale);

                msgbox(errMsg,'Invalid SRP Force Vector Scale Factor Value','error');
            end
        end

        % Value changed function: srpVectIncrText
        function srpVectIncrTextValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            app.srpVectIncrText.Value = attemptStrEval(app.srpVectIncrText.Value);

            profile = getSelectedProfile(app, handles);

            errMsg = {};

            incr = str2double(app.srpVectIncrText.Value);
            enteredStr = app.srpVectIncrText.Value;
            numberName = 'SRP Force Vector Data Point Increment';
            lb = 1;
            ub = Inf;
            isInt = true;
            errMsg = validateNumber(incr, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                profile.srpVectEntryIncr = incr;
            else
                app.srpVectIncrText.Value = fullAccNum2Str(round(profile.srpVectEntryIncr));

                msgbox(errMsg,'Invalid SRP Force Vector Data Point Increment','error');
            end  
        end

        % Value changed function: showDragVectorsCheckbox
        function showDragVectorsCheckboxValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            profile = getSelectedProfile(app, handles);
            profile.showDragVectors = logical(app.showDragVectorsCheckbox.Value);
        end

        % Value changed function: dragVectColorCombo
        function dragVectColorComboValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            profile = getSelectedProfile(app, handles);
            profile.dragVectColor = app.dragVectColorCombo.Value;
        end

        % Value changed function: dragVectLineStyleCombo
        function dragVectLineStyleComboValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            profile = getSelectedProfile(app, handles);
            profile.dragVectLineType = app.dragVectLineStyleCombo.Value;
        end

        % Value changed function: dragVectScaleText
        function dragVectScaleTextValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            app.dragVectScaleText.Value = attemptStrEval(app.dragVectScaleText.Value);

            profile = getSelectedProfile(app, handles);

            errMsg = {};

            scale = str2double(app.dragVectScaleText.Value);
            enteredStr = app.dragVectScaleText.Value;
            numberName = 'Drag Force Vector Scale Factor';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(scale, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                profile.dragVectScale = scale;
            else
                app.dragVectScaleText.Value = fullAccNum2Str(profile.dragVectScale);

                msgbox(errMsg,'Invalid Drag Force Vector Scale Factor Value','error');
            end
        end

        % Value changed function: dragVectIncrText
        function dragVectIncrTextValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            app.dragVectIncrText.Value = attemptStrEval(app.dragVectIncrText.Value);

            profile = getSelectedProfile(app, handles);

            errMsg = {};

            incr = str2double(app.dragVectIncrText.Value);
            enteredStr = app.dragVectIncrText.Value;
            numberName = 'Drag Force Vector Data Point Increment';
            lb = 1;
            ub = Inf;
            isInt = true;
            errMsg = validateNumber(incr, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                profile.dragVectEntryIncr = incr;
            else
                app.dragVectIncrText.Value = fullAccNum2Str(round(profile.dragVectEntryIncr));

                msgbox(errMsg,'Invalid Drag Force Vector Data Point Increment','error');
            end              
        end

        % Value changed function: PlotAllEventsCheckBox
        function PlotAllEventsCheckBoxValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            profile = getSelectedProfile(app, handles);
            profile.plotAllEvents = app.PlotAllEventsCheckBox.Value;

            if(app.PlotAllEventsCheckBox.Value)
                app.EventsToPlotListbox.Enable = "off";
            else
                app.EventsToPlotListbox.Enable = "on";
            end
        end

        % Value changed function: EventsToPlotListbox
        function EventsToPlotListboxValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            profile = getSelectedProfile(app, handles);
            
            eventsToPlot = app.EventsToPlotListbox.Value;
            if(isempty(eventsToPlot))
                eventsToPlot = LaunchVehicleEvent.empty(1,0);
            end

            profile.eventsToPlot = eventsToPlot;
        end

        % Value changed function: UseEventColorsinScriptListCheckBox
        function UseEventColorsinScriptListCheckBoxValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            profile = getSelectedProfile(app, handles);
            profile.scriptBoxUseEventColors = app.UseEventColorsinScriptListCheckBox.Value;

            if(app.UseEventColorsinScriptListCheckBox.Value)
                app.UseEventColorsinScriptListCheckBox.Enable = "off";
            else
                app.UseEventColorsinScriptListCheckBox.Enable = "on";
            end
        end

        % Value changed function: ShowAxesBoxCheckBox
        function ShowAxesBoxCheckBoxValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            profile = getSelectedProfile(app, handles);
            
            profile.showAxesBox = app.ShowAxesBoxCheckBox.Value;
        end

        % Value changed function: UseThemeforAxesColorsCheckBox
        function UseThemeforAxesColorsCheckBoxValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            profile = getSelectedProfile(app, handles);
            
            profile.useThemeForAxes = app.UseThemeforAxesColorsCheckBox.Value;
            app.setAxesPropertiesForUseThemeCheck();
        end

        % Value changed function: ShowTerrainContoursCheckBox
        function ShowTerrainContoursCheckBoxValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            profile = getSelectedProfile(app, handles);
            
            profile.showTerrainContours = app.ShowTerrainContoursCheckBox.Value;
        end

        % Value changed function: NumTerrainContourLevelsSpinner
        function NumTerrainContourLevelsSpinnerValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            profile = getSelectedProfile(app, handles);
            
            profile.numTerrainContourLevels = app.NumTerrainContourLevelsSpinner.Value;
        end

        % Value changed function: EnableSkyboxin3DViewCheckBox
        function EnableSkyboxin3DViewCheckBoxValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            profile = getSelectedProfile(app, handles);
            
            profile.useSkybox = logical(app.EnableSkyboxin3DViewCheckBox.Value);
            if(profile.useSkybox)
                app.SkyboxFileDropDownMenu.Enable = "on";
                app.SizeMultiplierEditField.Enable = "on";
                app.SkyboxPreviewImage.Enable = "on";
            else
                app.SkyboxFileDropDownMenu.Enable = "off";
                app.SizeMultiplierEditField.Enable = "off";
                app.SkyboxPreviewImage.Enable = "off";
            end
        end

        % Value changed function: SkyboxFileDropDownMenu
        function SkyboxFileDropDownMenuValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            profile = getSelectedProfile(app, handles);

            profile.skyBoxImgFileName = app.SkyboxFileDropDownMenu.Value;

            I = imread(profile.skyBoxImgFileName);
            I = flipud(I);
            I = imresize(I, 1, "bilinear","Antialiasing",true);

            profile.skyBoxImageI = I;

            app.SkyboxPreviewImage.ImageSource = profile.skyBoxImgFileName;
        end

        % Value changed function: SizeMultiplierEditField
        function SizeMultiplierEditFieldValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            profile = getSelectedProfile(app, handles);

            profile.skyboxRadiusMultiplier = app.SizeMultiplierEditField.Value;
        end

        % Value changed function: ShowCelestialBodiesonGroundTrackCheckBox
        function ShowCelestialBodiesonGroundTrackCheckBoxValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            profile = getSelectedProfile(app, handles);
            
            profile.showCelestialBodyGrdTracks = app.ShowCelestialBodiesonGroundTrackCheckBox.Value;
        end

        % Value changed function: ShowGroundObjsonGroundTrackCheckBox
        function ShowGroundObjsonGroundTrackCheckBoxValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            profile = getSelectedProfile(app, handles);
            
            profile.showGroundObjsGrdTracks = app.ShowGroundObjsonGroundTrackCheckBox.Value;
        end

        % Value changed function: ShowGeometricPointsonGroundTrackCheckBox
        function ShowGeometricPointsonGroundTrackCheckBoxValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            profile = getSelectedProfile(app, handles);
            
            profile.showGeomPointsGrdTracks = app.ShowGeometricPointsonGroundTrackCheckBox.Value;
        end

        % Value changed function: Render2DGroundTrackCheckBox
        function Render2DGroundTrackCheckBoxValueChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            profile = getSelectedProfile(app, handles);

            profile.showGrdTrk = logical(app.Render2DGroundTrackCheckBox.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_viewSettingsGUI = uifigure('Visible', 'off');
            app.lvd_viewSettingsGUI.Name = 'View Settings';
            app.lvd_viewSettingsGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_viewSettingsGUI.CloseRequestFcn = createCallbackFcn(app, @lvd_viewSettingsGUICloseRequest, true);
            app.lvd_viewSettingsGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_viewSettingsGUI_WindowKeyPressFcn, true);
            app.lvd_viewSettingsGUI.Tag = 'lvd_viewSettingsGUI';
            app.GridLayout = uigridlayout(app.lvd_viewSettingsGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'View Settings';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'View Profiles';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4 = uigridlayout(app.uipanel2);
            app.viewProfilesListbox = uilistbox(app.GridLayout4);
            app.viewProfilesListbox.Items = {'Listbox'};
            app.viewProfilesListbox.ValueChangedFcn = createCallbackFcn(app, @viewProfilesListbox_Callback, true);
            app.viewProfilesListbox.Tag = 'viewProfilesListbox';
            app.viewProfilesListbox.Tooltip = 'A list of the view profiles contained within this LVD mission.  View profiles describe how LVD displays the trajectory being designed in the display axes.';
            app.viewProfilesListbox.Value = 'Listbox';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.moveViewProfileDownButton = uibutton(app.GridLayout5, 'push');
            app.moveViewProfileDownButton.ButtonPushedFcn = createCallbackFcn(app, @moveViewProfileDownButton_Callback, true);
            app.moveViewProfileDownButton.Tag = 'moveViewProfileDownButton';
            app.moveViewProfileDownButton.Icon = 'down-arrow.png';
            app.moveViewProfileDownButton.Tooltip = 'Move selected view profile down in the list.';
            app.moveViewProfileDownButton.Text = '';
            app.moveViewProfileUpButton = uibutton(app.GridLayout5, 'push');
            app.moveViewProfileUpButton.ButtonPushedFcn = createCallbackFcn(app, @moveViewProfileUpButton_Callback, true);
            app.moveViewProfileUpButton.Tag = 'moveViewProfileUpButton';
            app.moveViewProfileUpButton.Icon = 'up-arrow.png';
            app.moveViewProfileUpButton.Tooltip = {'Move selected view profile up in the list.'};
            app.moveViewProfileUpButton.Text = '­';
            app.addViewProfileButton = uibutton(app.GridLayout4, 'push');
            app.addViewProfileButton.ButtonPushedFcn = createCallbackFcn(app, @addViewProfileButton_Callback, true);
            app.addViewProfileButton.Tag = 'addViewProfileButton';
            app.addViewProfileButton.Icon = 'add.png';
            app.addViewProfileButton.Tooltip = 'Add a view profile.';
            app.addViewProfileButton.Text = 'Add View Profile';
            app.removeViewProfileButton = uibutton(app.GridLayout4, 'push');
            app.removeViewProfileButton.ButtonPushedFcn = createCallbackFcn(app, @removeViewProfileButton_Callback, true);
            app.removeViewProfileButton.Tag = 'removeViewProfileButton';
            app.removeViewProfileButton.Icon = 'remove.png';
            app.removeViewProfileButton.Tooltip = 'Delete the selected view profile.  This cannot be undone.';
            app.removeViewProfileButton.Text = 'Remove View Profile';
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.ViewProfileDetailsTab = uitab(app.TabGroup);
            app.ViewProfileDetailsTab.Title = 'View Profile Details';
            app.GridLayout6 = uigridlayout(app.ViewProfileDetailsTab);
            app.uipanel4 = uipanel(app.GridLayout6);
            app.uipanel4.Title = 'Profile Description';
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout7 = uigridlayout(app.uipanel4);
            app.text3 = uilabel(app.GridLayout7);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Profile Name';
            app.nameText = uieditfield(app.GridLayout7, 'text');
            app.nameText.ValueChangedFcn = createCallbackFcn(app, @nameText_Callback, true);
            app.nameText.Tag = 'nameText';
            app.nameText.Tooltip = 'The name of the selected view profile. ';
            app.nameText.Value = 'Untitled Profile';
            app.text4 = uilabel(app.GridLayout7);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Profile Description';
            app.profileDesc = uitextarea(app.GridLayout7);
            app.profileDesc.ValueChangedFcn = createCallbackFcn(app, @profileDesc_Callback, true);
            app.profileDesc.Tag = 'profileDesc';
            app.profileDesc.Tooltip = 'The description of the selected view profile.';
            app.text24 = uilabel(app.GridLayout7);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Renderer';
            app.rendererCombo = uidropdown(app.GridLayout7);
            app.rendererCombo.Items = {'Pop-up Menu'};
            app.rendererCombo.ValueChangedFcn = createCallbackFcn(app, @rendererCombo_Callback, true);
            app.rendererCombo.Tag = 'rendererCombo';
            app.rendererCombo.Tooltip = 'Changes the method by which displays are rendered.  OpenGL is much quicker and more responsive, but painters can look better in some situations.';
            app.rendererCombo.Value = 'Pop-up Menu';
            app.setAsActiveProfileCheckbox = uicheckbox(app.GridLayout7);
            app.setAsActiveProfileCheckbox.ValueChangedFcn = createCallbackFcn(app, @setAsActiveProfileCheckbox_Callback, true);
            app.setAsActiveProfileCheckbox.Tag = 'setAsActiveProfileCheckbox';
            app.setAsActiveProfileCheckbox.Text = 'Set as Active View Profile';
            app.updateViewAxesCheckbox = uicheckbox(app.GridLayout7);
            app.updateViewAxesCheckbox.ValueChangedFcn = createCallbackFcn(app, @updateViewAxesCheckbox_Callback, true);
            app.updateViewAxesCheckbox.Tag = 'updateViewAxesCheckbox';
            app.updateViewAxesCheckbox.Tooltip = 'When checked, the view system will update the axes limits to show the entire trajectory.  If unchecked, the view axes limits will remain fixed and will only update when panning or zooming around the display.';
            app.updateViewAxesCheckbox.Text = 'Update View Axes Limits';
            app.EventsPanel = uipanel(app.GridLayout6);
            app.EventsPanel.Title = 'Events';
            app.GridLayout35 = uigridlayout(app.EventsPanel);
            app.PlotAllEventsCheckBox = uicheckbox(app.GridLayout35);
            app.PlotAllEventsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotAllEventsCheckBoxValueChanged, true);
            app.PlotAllEventsCheckBox.Tooltip = {'When checked, all events in the mission will be plotted.  When unchecked, only the selected events in the listbox below will be plotted.'};
            app.PlotAllEventsCheckBox.Text = 'Plot All Events';
            app.EventsToPlotListbox = uilistbox(app.GridLayout35);
            app.EventsToPlotListbox.Multiselect = 'on';
            app.EventsToPlotListbox.ValueChangedFcn = createCallbackFcn(app, @EventsToPlotListboxValueChanged, true);
            app.EventsToPlotListbox.Tooltip = {'Select the events you wish to plot.  Unselected events will not be plotted.  The plotting style must be set to "All" and not "SoI" for this to take effect.'};
            app.EventsToPlotListbox.Value = {'Item 1'};
            app.UserInterfacePanel = uipanel(app.GridLayout6);
            app.UserInterfacePanel.Title = 'User Interface';
            app.GridLayout36 = uigridlayout(app.UserInterfacePanel);
            app.UseEventColorsinScriptListCheckBox = uicheckbox(app.GridLayout36);
            app.UseEventColorsinScriptListCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseEventColorsinScriptListCheckBoxValueChanged, true);
            app.UseEventColorsinScriptListCheckBox.Tooltip = {'When checked, the sequential events listbox will color the events by their trajectory color. '};
            app.UseEventColorsinScriptListCheckBox.Text = 'Use Event Colors in Script List';
            app.Render2DGroundTrackCheckBox = uicheckbox(app.GridLayout36);
            app.Render2DGroundTrackCheckBox.ValueChangedFcn = createCallbackFcn(app, @Render2DGroundTrackCheckBoxValueChanged, true);
            app.Render2DGroundTrackCheckBox.Text = 'Render 2D Ground Track';
            app.ViewAxesOptionsTab = uitab(app.TabGroup);
            app.ViewAxesOptionsTab.Title = 'View Axes Options ';
            app.GridLayout8 = uigridlayout(app.ViewAxesOptionsTab);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.uipanel7 = uipanel(app.GridLayout9);
            app.uipanel7.Title = 'Trajectory Options';
            app.uipanel7.Tag = 'uipanel7';
            app.GridLayout10 = uigridlayout(app.uipanel7);
            app.text11 = uilabel(app.GridLayout10);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Events Plotting Style';
            app.evtsPlottingStyleCombo = uidropdown(app.GridLayout10);
            app.evtsPlottingStyleCombo.Items = {'Pop-up Menu'};
            app.evtsPlottingStyleCombo.ValueChangedFcn = createCallbackFcn(app, @evtsPlottingStyleCombo_Callback, true);
            app.evtsPlottingStyleCombo.Tag = 'evtsPlottingStyleCombo';
            app.evtsPlottingStyleCombo.Value = 'Pop-up Menu';
            app.text12 = uilabel(app.GridLayout10);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Trajectory View Frame';
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.trajViewFrameCombo = uidropdown(app.GridLayout11);
            app.trajViewFrameCombo.Items = {'Pop-up Menu'};
            app.trajViewFrameCombo.ValueChangedFcn = createCallbackFcn(app, @trajViewFrameCombo_Callback, true);
            app.trajViewFrameCombo.Tag = 'trajViewFrameCombo';
            app.trajViewFrameCombo.Value = 'Pop-up Menu';
            app.setFrameOptionsButton = uibutton(app.GridLayout11, 'push');
            app.setFrameOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @setFrameOptionsButton_Callback, true);
            app.setFrameOptionsButton.Tag = 'setFrameOptionsButton';
            app.setFrameOptionsButton.Tooltip = 'Set Frame Properties';
            app.setFrameOptionsButton.Text = 'Properties';
            app.uipanel6 = uipanel(app.GridLayout9);
            app.uipanel6.Title = 'SoI and Celestial Body Options';
            app.uipanel6.Tag = 'uipanel6';
            app.GridLayout12 = uigridlayout(app.uipanel6);
            app.showSoICheckbox = uicheckbox(app.GridLayout12);
            app.showSoICheckbox.ValueChangedFcn = createCallbackFcn(app, @showSoICheckbox_Callback, true);
            app.showSoICheckbox.Tag = 'showSoICheckbox';
            app.showSoICheckbox.Text = 'Show Sphere of Influence Radius';
            app.showFixedFrameGridCheckbox = uicheckbox(app.GridLayout12);
            app.showFixedFrameGridCheckbox.ValueChangedFcn = createCallbackFcn(app, @showFixedFrameGridCheckbox_Callback, true);
            app.showFixedFrameGridCheckbox.Tag = 'showFixedFrameGridCheckbox';
            app.showFixedFrameGridCheckbox.Tooltip = 'Shows a latitude/longitude grid when the trajectory view frame is set to Body-Fixed.';
            app.showFixedFrameGridCheckbox.Text = 'Show Fixed Frame Grid';
            app.uipanel10 = uipanel(app.GridLayout9);
            app.uipanel10.Title = 'Display Coordinate System';
            app.uipanel10.Tag = 'uipanel10';
            app.GridLayout13 = uigridlayout(app.uipanel10);
            app.displayXAxisCheckbox = uicheckbox(app.GridLayout13);
            app.displayXAxisCheckbox.ValueChangedFcn = createCallbackFcn(app, @displayXAxisCheckbox_Callback, true);
            app.displayXAxisCheckbox.Tag = 'displayXAxisCheckbox';
            app.displayXAxisCheckbox.Tooltip = 'When checked, shows the current coordinate system X axis in red.';
            app.displayXAxisCheckbox.Text = 'Display X Axis';
            app.displayYAxisCheckbox = uicheckbox(app.GridLayout13);
            app.displayYAxisCheckbox.ValueChangedFcn = createCallbackFcn(app, @displayYAxisCheckbox_Callback, true);
            app.displayYAxisCheckbox.Tag = 'displayYAxisCheckbox';
            app.displayYAxisCheckbox.Tooltip = 'When checked, shows the current coordinate system Y axis in green.';
            app.displayYAxisCheckbox.Text = 'Display Y Axis';
            app.displayZAxisCheckbox = uicheckbox(app.GridLayout13);
            app.displayZAxisCheckbox.ValueChangedFcn = createCallbackFcn(app, @displayZAxisCheckbox_Callback, true);
            app.displayZAxisCheckbox.Tag = 'displayZAxisCheckbox';
            app.displayZAxisCheckbox.Tooltip = 'When checked, shows the current coordinate system Z axis in blue.';
            app.displayZAxisCheckbox.Text = 'Display Z Axis';
            app.ProjectionTypePanel = uipanel(app.GridLayout9);
            app.ProjectionTypePanel.Title = 'Projection Type';
            app.GridLayout33 = uigridlayout(app.ProjectionTypePanel);
            app.ViewProjCombo = uidropdown(app.GridLayout33);
            app.ViewProjCombo.ValueChangedFcn = createCallbackFcn(app, @ViewProjComboValueChanged, true);
            app.ViewProjCombo.Tooltip = {'Determines whether or not the 3D scene in the display axes uses a perspective or orthographic projection.'};
            app.GridLayout14 = uigridlayout(app.GridLayout8);
            app.uipanel5 = uipanel(app.GridLayout14);
            app.uipanel5.Title = 'Axes Properties';
            app.uipanel5.Tag = 'uipanel5';
            app.GridLayout16 = uigridlayout(app.uipanel5);
            app.UseThemeforAxesColorsCheckBox = uicheckbox(app.GridLayout16);
            app.UseThemeforAxesColorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseThemeforAxesColorsCheckBoxValueChanged, true);
            app.UseThemeforAxesColorsCheckBox.Text = 'Use Theme for Axes Colors';
            app.text5 = uilabel(app.GridLayout16);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Background Color';
            app.axesBackgroundColorCombo = uidropdown(app.GridLayout16);
            app.axesBackgroundColorCombo.Items = {'Pop-up Menu'};
            app.axesBackgroundColorCombo.ValueChangedFcn = createCallbackFcn(app, @axesBackgroundColorCombo_Callback, true);
            app.axesBackgroundColorCombo.Tag = 'axesBackgroundColorCombo';
            app.axesBackgroundColorCombo.Value = 'Pop-up Menu';
            app.text8 = uilabel(app.GridLayout16);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Grid Type';
            app.gridTypeCombo = uidropdown(app.GridLayout16);
            app.gridTypeCombo.Items = {'Pop-up Menu'};
            app.gridTypeCombo.ValueChangedFcn = createCallbackFcn(app, @gridTypeCombo_Callback, true);
            app.gridTypeCombo.Tag = 'gridTypeCombo';
            app.gridTypeCombo.Value = 'Pop-up Menu';
            app.text6 = uilabel(app.GridLayout16);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Grid Major Lines Color';
            app.majorGridLinesColorCombo = uidropdown(app.GridLayout16);
            app.majorGridLinesColorCombo.Items = {'Pop-up Menu'};
            app.majorGridLinesColorCombo.ValueChangedFcn = createCallbackFcn(app, @majorGridLinesColorCombo_Callback, true);
            app.majorGridLinesColorCombo.Tag = 'majorGridLinesColorCombo';
            app.majorGridLinesColorCombo.Value = 'Pop-up Menu';
            app.text7 = uilabel(app.GridLayout16);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Grid Minor Lines Color';
            app.minorGridLinesColorCombo = uidropdown(app.GridLayout16);
            app.minorGridLinesColorCombo.Items = {'Pop-up Menu'};
            app.minorGridLinesColorCombo.ValueChangedFcn = createCallbackFcn(app, @minorGridLinesColorCombo_Callback, true);
            app.minorGridLinesColorCombo.Tag = 'minorGridLinesColorCombo';
            app.minorGridLinesColorCombo.Value = 'Pop-up Menu';
            app.text9 = uilabel(app.GridLayout16);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Grid Transparency';
            app.GridLayout17 = uigridlayout(app.GridLayout16);
            app.gridAlphaText = uieditfield(app.GridLayout17, 'text');
            app.gridAlphaText.ValueChangedFcn = createCallbackFcn(app, @gridAlphaText_Callback, true);
            app.gridAlphaText.Tag = 'gridAlphaText';
            app.gridAlphaText.Tooltip = 'Controls the transparency of the grid lines in the figure.  Must be between 0 and 100 inclusive, where 100 is fully opaque.';
            app.gridAlphaText.Value = 'Pop-up Menu';
            app.text10 = uilabel(app.GridLayout17);
            app.text10.Tag = 'text10';
            app.text10.Text = '%';
            app.ShowAxesBoxCheckBox = uicheckbox(app.GridLayout16);
            app.ShowAxesBoxCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowAxesBoxCheckBoxValueChanged, true);
            app.ShowAxesBoxCheckBox.Tooltip = {'When checked, the axes rulers, grid lines, and tick marks will show.  When unchecked, these items will be hidden.'};
            app.ShowAxesBoxCheckBox.Text = 'Show Axes Box';
            app.uipanel12 = uipanel(app.GridLayout14);
            app.uipanel12.Title = 'Sun Lighting and Vector';
            app.uipanel12.Tag = 'uipanel12';
            app.GridLayout15 = uigridlayout(app.uipanel12);
            app.showSunVectorCheckbox = uicheckbox(app.GridLayout15);
            app.showSunVectorCheckbox.ValueChangedFcn = createCallbackFcn(app, @showSunVectorCheckbox_Callback, true);
            app.showSunVectorCheckbox.Tag = 'showSunVectorCheckbox';
            app.showSunVectorCheckbox.Tooltip = 'When checked, displays a yellow sun vector arrow pointing to the Sun from the center of the coordinate system.';
            app.showSunVectorCheckbox.Text = 'Show Sun Vector';
            app.showSunLightingCheckbox = uicheckbox(app.GridLayout15);
            app.showSunLightingCheckbox.ValueChangedFcn = createCallbackFcn(app, @showSunLightingCheckbox_Callback, true);
            app.showSunLightingCheckbox.Tag = 'showSunLightingCheckbox';
            app.showSunLightingCheckbox.Tooltip = 'When checked, enables a light source to simulate solar lighting from the Sun on celestial bodies.';
            app.showSunLightingCheckbox.Text = 'Show Sun Lighting';
            app.GridLayout39 = uigridlayout(app.GridLayout8);
            app.GroundTrackDisplayPanel = uipanel(app.GridLayout39);
            app.GroundTrackDisplayPanel.Title = 'Ground Track Display';
            app.GridLayout37 = uigridlayout(app.GroundTrackDisplayPanel);
            app.ShowTerrainContoursCheckBox = uicheckbox(app.GridLayout37);
            app.ShowTerrainContoursCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowTerrainContoursCheckBoxValueChanged, true);
            app.ShowTerrainContoursCheckBox.Tooltip = {'When checked will show terrain contours if height map data has been loaded for the celestial body over which the ground track is plotted.'};
            app.ShowTerrainContoursCheckBox.Text = 'Show Terrain Contours';
            app.GridLayout38 = uigridlayout(app.GridLayout37);
            app.NumContourLevelsLabel = uilabel(app.GridLayout38);
            app.NumContourLevelsLabel.Tooltip = {'Number of levels to show in the terrain elevation contour map.'};
            app.NumContourLevelsLabel.Text = 'Num. Contour Levels';
            app.NumTerrainContourLevelsSpinner = uispinner(app.GridLayout38);
            app.NumTerrainContourLevelsSpinner.Limits = [2 20];
            app.NumTerrainContourLevelsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumTerrainContourLevelsSpinnerValueChanged, true);
            app.NumTerrainContourLevelsSpinner.Value = 2;
            app.SkyboxPanel = uipanel(app.GridLayout39);
            app.SkyboxPanel.Title = 'Skybox';
            app.GridLayout40 = uigridlayout(app.SkyboxPanel);
            app.EnableSkyboxin3DViewCheckBox = uicheckbox(app.GridLayout40);
            app.EnableSkyboxin3DViewCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableSkyboxin3DViewCheckBoxValueChanged, true);
            app.EnableSkyboxin3DViewCheckBox.Text = 'Enable Skybox in 3D View';
            app.SkyboxFileDropDownMenu = uidropdown(app.GridLayout40);
            app.SkyboxFileDropDownMenu.Items = {'DarkStarsSkyBox.png', 'DefaultKspSkyBox.png', 'MilkyWaySkyBox.png', 'eso0932a.png', 'CrabNebulaSkyBox.png'};
            app.SkyboxFileDropDownMenu.ValueChangedFcn = createCallbackFcn(app, @SkyboxFileDropDownMenuValueChanged, true);
            app.SkyboxFileDropDownMenu.Value = 'DarkStarsSkyBox.png';
            app.GridLayout41 = uigridlayout(app.GridLayout40);
            app.SizeMultiplierEditFieldLabel = uilabel(app.GridLayout41);
            app.SizeMultiplierEditFieldLabel.Text = 'Size Multiplier';
            app.SizeMultiplierEditField = uieditfield(app.GridLayout41, 'numeric');
            app.SizeMultiplierEditField.Limits = [1 Inf];
            app.SizeMultiplierEditField.ValueDisplayFormat = '%11.4f';
            app.SizeMultiplierEditField.ValueChangedFcn = createCallbackFcn(app, @SizeMultiplierEditFieldValueChanged, true);
            app.SizeMultiplierEditField.Tooltip = {'This value sets the multipler on the radius of the sphere of tthe skybox.  The initial radius is defined as the distance between the camera and the farthest object in the scene, but the skybox needs tto be larger than that to look correct.  Settings between 1.5 and 10 are usually correct.  If the camera starts clipping lines or objects that are closer to the camera, lower this value.'};
            app.SizeMultiplierEditField.Value = 1;
            app.Panel = uipanel(app.GridLayout40);
            app.GridLayout42 = uigridlayout(app.Panel);
            app.SkyboxPreviewImage = uiimage(app.GridLayout42);
            app.SpacecraftCeletialBodiesTab = uitab(app.TabGroup);
            app.SpacecraftCeletialBodiesTab.Title = 'Spacecraft & Celetial Bodies';
            app.GridLayout18 = uigridlayout(app.SpacecraftCeletialBodiesTab);
            app.uipanel9 = uipanel(app.GridLayout18);
            app.uipanel9.Title = 'Bodies to Plot';
            app.uipanel9.Tag = 'uipanel9';
            app.GridLayout19 = uigridlayout(app.uipanel9);
            app.bodiesToPlotListbox = uilistbox(app.GridLayout19);
            app.bodiesToPlotListbox.Items = {'Listbox'};
            app.bodiesToPlotListbox.Multiselect = 'on';
            app.bodiesToPlotListbox.ValueChangedFcn = createCallbackFcn(app, @bodiesToPlotListbox_Callback, true);
            app.bodiesToPlotListbox.Tag = 'bodiesToPlotListbox';
            app.bodiesToPlotListbox.Tooltip = 'Celestial bodies selected here will have their orbits plotted.  The central body is always plotted, regardless of selections here.';
            app.bodiesToPlotListbox.Value = {'Listbox'};
            app.text15 = uilabel(app.GridLayout19);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Body Render Style';
            app.bodyPlottingStyleCombo = uidropdown(app.GridLayout19);
            app.bodyPlottingStyleCombo.Items = {'Pop-up Menu'};
            app.bodyPlottingStyleCombo.ValueChangedFcn = createCallbackFcn(app, @bodyPlottingStyleCombo_Callback, true);
            app.bodyPlottingStyleCombo.Tag = 'bodyPlottingStyleCombo';
            app.bodyPlottingStyleCombo.Tooltip = 'Change the plotting style for bodies plotted.  Only applies to bodies beyond the reference frame origin.  The reference frame origin body always renders as a 3D mesh sphere.';
            app.bodyPlottingStyleCombo.Value = 'Pop-up Menu';
            app.text25 = uilabel(app.GridLayout19);
            app.text25.Tag = 'text25';
            app.text25.Text = 'Mesh Edge Transparency';
            app.GridLayout20 = uigridlayout(app.GridLayout19);
            app.meshEdgeAlphaText = uieditfield(app.GridLayout20, 'text');
            app.meshEdgeAlphaText.ValueChangedFcn = createCallbackFcn(app, @meshEdgeAlphaText_Callback, true);
            app.meshEdgeAlphaText.Tag = 'meshEdgeAlphaText';
            app.meshEdgeAlphaText.Tooltip = 'Controls the edge transparency of mesh spheres which are rendered.  Must be between 0 and 100 inclusive, where 100 is fully opaque.  Note that setting this to something other than 0 or 1 will disable anti-aliasing in the figure.';
            app.meshEdgeAlphaText.Value = 'Pop-up Menu';
            app.text26 = uilabel(app.GridLayout20);
            app.text26.Tag = 'text26';
            app.text26.Text = '%';
            app.showCbAtmo = uicheckbox(app.GridLayout19);
            app.showCbAtmo.ValueChangedFcn = createCallbackFcn(app, @showCbAtmo_Callback, true);
            app.showCbAtmo.Tag = 'showCbAtmo';
            app.showCbAtmo.Text = 'Show Central Body Atmosphere';
            app.GridLayout21 = uigridlayout(app.GridLayout18);
            app.uipanel13 = uipanel(app.GridLayout21);
            app.uipanel13.Title = 'Spacecraft Body Axes';
            app.uipanel13.Tag = 'uipanel13';
            app.GridLayout22 = uigridlayout(app.uipanel13);
            app.showScBodyAxesCheckbox = uicheckbox(app.GridLayout22);
            app.showScBodyAxesCheckbox.ValueChangedFcn = createCallbackFcn(app, @showScBodyAxesCheckbox_Callback, true);
            app.showScBodyAxesCheckbox.Tag = 'showScBodyAxesCheckbox';
            app.showScBodyAxesCheckbox.Tooltip = 'When checked, a set of spacecraft body axes will be drawn on the trajectory at the location of the vehicle.  Red = X, Green = Y, Blue = Z.';
            app.showScBodyAxesCheckbox.Text = 'Show S/C Body Axes';
            app.text21 = uilabel(app.GridLayout22);
            app.text21.Tag = 'text21';
            app.text21.Text = 'S/C Body Axes Scale';
            app.GridLayout23 = uigridlayout(app.GridLayout22);
            app.scBodyAxesScaleText = uieditfield(app.GridLayout23, 'text');
            app.scBodyAxesScaleText.ValueChangedFcn = createCallbackFcn(app, @scBodyAxesScaleText_Callback, true);
            app.scBodyAxesScaleText.Tag = 'scBodyAxesScaleText';
            app.scBodyAxesScaleText.Tooltip = 'Draws the S/C body axes with each axis of this length.';
            app.scBodyAxesScaleText.Value = 'Pop-up Menu';
            app.text22 = uilabel(app.GridLayout23);
            app.text22.Tag = 'text22';
            app.text22.Text = 'km';
            app.uipanel11 = uipanel(app.GridLayout21);
            app.uipanel11.Title = 'Thrust Vectors';
            app.uipanel11.Tag = 'uipanel11';
            app.GridLayout24 = uigridlayout(app.uipanel11);
            app.showThrustVectorsCheckbox = uicheckbox(app.GridLayout24);
            app.showThrustVectorsCheckbox.ValueChangedFcn = createCallbackFcn(app, @showThrustVectorsCheckbox_Callback, true);
            app.showThrustVectorsCheckbox.Tag = 'showThrustVectorsCheckbox';
            app.showThrustVectorsCheckbox.Tooltip = {'When checked thrust vectors will be drawn on the trajectory where Thrust Force is enabled for an event and an engine is producing thrust.'};
            app.showThrustVectorsCheckbox.Text = 'Show Thrust Vectors';
            app.GridLayout25 = uigridlayout(app.GridLayout24);
            app.thrustVectColorCombo = uidropdown(app.GridLayout25);
            app.thrustVectColorCombo.Items = {'Pop-up Menu'};
            app.thrustVectColorCombo.ValueChangedFcn = createCallbackFcn(app, @thrustVectColorCombo_Callback, true);
            app.thrustVectColorCombo.Tag = 'thrustVectColorCombo';
            app.thrustVectColorCombo.Tooltip = 'The color of the thrust vectors drawn on the trajectory';
            app.thrustVectColorCombo.Value = 'Pop-up Menu';
            app.thrustVectLineStyleCombo = uidropdown(app.GridLayout25);
            app.thrustVectLineStyleCombo.Items = {'Pop-up Menu'};
            app.thrustVectLineStyleCombo.ValueChangedFcn = createCallbackFcn(app, @thrustVectLineStyleCombo_Callback, true);
            app.thrustVectLineStyleCombo.Tag = 'thrustVectLineStyleCombo';
            app.thrustVectLineStyleCombo.Tooltip = 'The style of the lines used to drawn thrust vectors onto the trajectory.';
            app.thrustVectLineStyleCombo.Value = 'Pop-up Menu';
            app.text18 = uilabel(app.GridLayout24);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Thrust Vector Scale';
            app.GridLayout26 = uigridlayout(app.GridLayout24);
            app.thrustVectScaleText = uieditfield(app.GridLayout26, 'text');
            app.thrustVectScaleText.ValueChangedFcn = createCallbackFcn(app, @thrustVectScaleText_Callback, true);
            app.thrustVectScaleText.Tag = 'thrustVectScaleText';
            app.thrustVectScaleText.Tooltip = 'The scale factor used to display thrust vectors.  For example, a 2 kN thrust vector with a scale of 3 km/kN will be 2*3 = 6 km long on the trajectory plot.';
            app.thrustVectScaleText.Value = 'Pop-up Menu';
            app.text20 = uilabel(app.GridLayout26);
            app.text20.Tag = 'text20';
            app.text20.Text = 'km/kN';
            app.text19 = uilabel(app.GridLayout24);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Trajectory Data Point Increment';
            app.thrustVectIncrText = uieditfield(app.GridLayout24, 'text');
            app.thrustVectIncrText.ValueChangedFcn = createCallbackFcn(app, @thrustVectIncrText_Callback, true);
            app.thrustVectIncrText.Tag = 'thrustVectIncrText';
            app.thrustVectIncrText.Tooltip = 'When 1, the thrust vector will be computed at all points along the trajectory.  When 2, every other point will be used, etc.  Must be an integer greater than or equal to 1.';
            app.thrustVectIncrText.Value = 'Pop-up Menu';
            app.CelestialGroundTrackDisplayPanel = uipanel(app.GridLayout21);
            app.CelestialGroundTrackDisplayPanel.Title = 'Celestial Ground Track Display';
            app.GridLayout43 = uigridlayout(app.CelestialGroundTrackDisplayPanel);
            app.ShowCelestialBodiesonGroundTrackCheckBox = uicheckbox(app.GridLayout43);
            app.ShowCelestialBodiesonGroundTrackCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCelestialBodiesonGroundTrackCheckBoxValueChanged, true);
            app.ShowCelestialBodiesonGroundTrackCheckBox.Text = 'Show Celestial Bodies on Ground Track';
            app.GridLayout34 = uigridlayout(app.GridLayout18);
            app.uipanel11_3 = uipanel(app.GridLayout34);
            app.uipanel11_3.Title = 'Drag Force Vectors';
            app.uipanel11_3.Tag = 'uipanel11';
            app.GridLayout24_3 = uigridlayout(app.uipanel11_3);
            app.showDragVectorsCheckbox = uicheckbox(app.GridLayout24_3);
            app.showDragVectorsCheckbox.ValueChangedFcn = createCallbackFcn(app, @showDragVectorsCheckboxValueChanged, true);
            app.showDragVectorsCheckbox.Tooltip = {'When checked aerodynamic drag force vectors will be drawn on the trajectory if Drag Force is enabled for that event.'};
            app.showDragVectorsCheckbox.Text = 'Show Drag Force Vectors';
            app.GridLayout25_3 = uigridlayout(app.GridLayout24_3);
            app.dragVectColorCombo = uidropdown(app.GridLayout25_3);
            app.dragVectColorCombo.Items = {'Pop-up Menu'};
            app.dragVectColorCombo.ValueChangedFcn = createCallbackFcn(app, @dragVectColorComboValueChanged, true);
            app.dragVectColorCombo.Tooltip = 'The color of the thrust vectors drawn on the trajectory';
            app.dragVectColorCombo.Value = 'Pop-up Menu';
            app.dragVectLineStyleCombo = uidropdown(app.GridLayout25_3);
            app.dragVectLineStyleCombo.Items = {'Pop-up Menu'};
            app.dragVectLineStyleCombo.ValueChangedFcn = createCallbackFcn(app, @dragVectLineStyleComboValueChanged, true);
            app.dragVectLineStyleCombo.Tooltip = 'The style of the lines used to drawn thrust vectors onto the trajectory.';
            app.dragVectLineStyleCombo.Value = 'Pop-up Menu';
            app.text18_3 = uilabel(app.GridLayout24_3);
            app.text18_3.Tag = 'text18';
            app.text18_3.Text = 'Drag Force Vector Scale';
            app.GridLayout26_3 = uigridlayout(app.GridLayout24_3);
            app.dragVectScaleText = uieditfield(app.GridLayout26_3, 'text');
            app.dragVectScaleText.ValueChangedFcn = createCallbackFcn(app, @dragVectScaleTextValueChanged, true);
            app.dragVectScaleText.Tooltip = {'The scale factor used to display drag vectors.  For example a 2 kN drag vector with a scale of 3 km/kN will be 2*3 = 6 km long on the trajectory plot.'};
            app.dragVectScaleText.Value = 'Pop-up Menu';
            app.text20_3 = uilabel(app.GridLayout26_3);
            app.text20_3.Tag = 'text20';
            app.text20_3.Text = 'km/kN';
            app.text19_3 = uilabel(app.GridLayout24_3);
            app.text19_3.Tag = 'text19';
            app.text19_3.Text = 'Trajectory Data Point Increment';
            app.dragVectIncrText = uieditfield(app.GridLayout24_3, 'text');
            app.dragVectIncrText.ValueChangedFcn = createCallbackFcn(app, @dragVectIncrTextValueChanged, true);
            app.dragVectIncrText.Tooltip = {'When 1 the drag force vector will be computed at all points along the trajectory.  When 2 every other point will be used etc.  Must be an integer greater than or equal to 1.'};
            app.dragVectIncrText.Value = 'Pop-up Menu';
            app.uipanel11_2 = uipanel(app.GridLayout34);
            app.uipanel11_2.Title = 'Solar Radiation Pressure Vectors';
            app.uipanel11_2.Tag = 'uipanel11';
            app.GridLayout24_2 = uigridlayout(app.uipanel11_2);
            app.showSrpVectorsCheckbox = uicheckbox(app.GridLayout24_2);
            app.showSrpVectorsCheckbox.ValueChangedFcn = createCallbackFcn(app, @showSrpVectorsCheckboxValueChanged, true);
            app.showSrpVectorsCheckbox.Tooltip = {'When checked solar radiation pressure force vectors will be drawn on the trajectory if Solar Radiation Pressure Force is enabled for that event.'};
            app.showSrpVectorsCheckbox.Text = 'Show SRP Force Vectors';
            app.GridLayout25_2 = uigridlayout(app.GridLayout24_2);
            app.srpVectColorCombo = uidropdown(app.GridLayout25_2);
            app.srpVectColorCombo.Items = {'Pop-up Menu'};
            app.srpVectColorCombo.ValueChangedFcn = createCallbackFcn(app, @srpVectColorComboValueChanged, true);
            app.srpVectColorCombo.Tooltip = 'The color of the thrust vectors drawn on the trajectory';
            app.srpVectColorCombo.Value = 'Pop-up Menu';
            app.srpVectLineStyleCombo = uidropdown(app.GridLayout25_2);
            app.srpVectLineStyleCombo.Items = {'Pop-up Menu'};
            app.srpVectLineStyleCombo.ValueChangedFcn = createCallbackFcn(app, @srpVectLineStyleComboValueChanged, true);
            app.srpVectLineStyleCombo.Tooltip = 'The style of the lines used to drawn thrust vectors onto the trajectory.';
            app.srpVectLineStyleCombo.Value = 'Pop-up Menu';
            app.text18_2 = uilabel(app.GridLayout24_2);
            app.text18_2.Tag = 'text18';
            app.text18_2.Text = 'SRP Vector Scale';
            app.GridLayout26_2 = uigridlayout(app.GridLayout24_2);
            app.srpVectScaleText = uieditfield(app.GridLayout26_2, 'text');
            app.srpVectScaleText.ValueChangedFcn = createCallbackFcn(app, @srpVectScaleTextValueChanged, true);
            app.srpVectScaleText.Tooltip = {'The scale factor used to display SRP vectors.  For example a 2 kN SRP vector with a scale of 3 km/kN will be 2*3 = 6 km long on the trajectory plot.'};
            app.srpVectScaleText.Value = 'Pop-up Menu';
            app.text20_2 = uilabel(app.GridLayout26_2);
            app.text20_2.Tag = 'text20';
            app.text20_2.Text = 'km/N';
            app.text19_2 = uilabel(app.GridLayout24_2);
            app.text19_2.Tag = 'text19';
            app.text19_2.Text = 'Trajectory Data Point Increment';
            app.srpVectIncrText = uieditfield(app.GridLayout24_2, 'text');
            app.srpVectIncrText.ValueChangedFcn = createCallbackFcn(app, @srpVectIncrTextValueChanged, true);
            app.srpVectIncrText.Tooltip = {'When 1 the SRP force vector will be computed at all points along the trajectory.  When 2 every other point will be used etc.  Must be an integer greater than or equal to 1.'};
            app.srpVectIncrText.Value = 'Pop-up Menu';
            app.GroundObjectsTab = uitab(app.TabGroup);
            app.GroundObjectsTab.Title = 'Ground Objects';
            app.GridLayout27 = uigridlayout(app.GroundObjectsTab);
            app.uipanel14 = uipanel(app.GridLayout27);
            app.uipanel14.Title = 'Ground Objects to Plot';
            app.uipanel14.Tag = 'uipanel14';
            app.GridLayout28 = uigridlayout(app.uipanel14);
            app.grdObjsListbox = uilistbox(app.GridLayout28);
            app.grdObjsListbox.Items = {'Listbox'};
            app.grdObjsListbox.Multiselect = 'on';
            app.grdObjsListbox.ValueChangedFcn = createCallbackFcn(app, @grdObjsListbox_Callback, true);
            app.grdObjsListbox.Tag = 'grdObjsListbox';
            app.grdObjsListbox.Value = {'Listbox'};
            app.showGrdObjTrksCheckbox = uicheckbox(app.GridLayout28);
            app.showGrdObjTrksCheckbox.ValueChangedFcn = createCallbackFcn(app, @showGrdObjTrksCheckbox_Callback, true);
            app.showGrdObjTrksCheckbox.Tag = 'showGrdObjTrksCheckbox';
            app.showGrdObjTrksCheckbox.Text = 'Show Ground Tracks';
            app.showLoSToScCheckbox = uicheckbox(app.GridLayout28);
            app.showLoSToScCheckbox.ValueChangedFcn = createCallbackFcn(app, @showLoSToScCheckbox_Callback, true);
            app.showLoSToScCheckbox.Tag = 'showLoSToScCheckbox';
            app.showLoSToScCheckbox.Tooltip = 'When checked, traces a line between the spacecraft and the ground object when the ground object is visible from the spacecraft.';
            app.showLoSToScCheckbox.Text = 'Show Line Of Sight to Vehicle';
            app.GridLayout44 = uigridlayout(app.GridLayout27);
            app.GroundTrackDisplayPanel_2 = uipanel(app.GridLayout44);
            app.GroundTrackDisplayPanel_2.Title = 'Ground Track Display';
            app.GridLayout43_2 = uigridlayout(app.GroundTrackDisplayPanel_2);
            app.ShowGroundObjsonGroundTrackCheckBox = uicheckbox(app.GridLayout43_2);
            app.ShowGroundObjsonGroundTrackCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowGroundObjsonGroundTrackCheckBoxValueChanged, true);
            app.ShowGroundObjsonGroundTrackCheckBox.Text = 'Show Ground Objs on Ground Track';
            app.GeometryTab = uitab(app.TabGroup);
            app.GeometryTab.Title = 'Geometry';
            app.GridLayout29 = uigridlayout(app.GeometryTab);
            app.uipanel15 = uipanel(app.GridLayout29);
            app.uipanel15.Title = 'Geometric Objects to Plot';
            app.uipanel15.Tag = 'uipanel15';
            app.GridLayout30 = uigridlayout(app.uipanel15);
            app.text27 = uilabel(app.GridLayout30);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Points';
            app.text30 = uilabel(app.GridLayout30);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Angles';
            app.pointsToPlotListbox = uilistbox(app.GridLayout30);
            app.pointsToPlotListbox.Items = {'Listbox'};
            app.pointsToPlotListbox.Multiselect = 'on';
            app.pointsToPlotListbox.ValueChangedFcn = createCallbackFcn(app, @pointsToPlotListbox_Callback, true);
            app.pointsToPlotListbox.Tag = 'pointsToPlotListbox';
            app.pointsToPlotListbox.Tooltip = 'Only points that have plotting options can be plotted here.  Celestial body points, ground object points, and vehicle points should be plotted by their respective options and not through the associated point.';
            app.pointsToPlotListbox.Value = {'Listbox'};
            app.anglesToPlotListbox = uilistbox(app.GridLayout30);
            app.anglesToPlotListbox.Items = {'Listbox'};
            app.anglesToPlotListbox.Multiselect = 'on';
            app.anglesToPlotListbox.ValueChangedFcn = createCallbackFcn(app, @anglesToPlotListbox_Callback, true);
            app.anglesToPlotListbox.Tag = 'anglesToPlotListbox';
            app.anglesToPlotListbox.Value = {'Listbox'};
            app.text28 = uilabel(app.GridLayout30);
            app.text28.Tag = 'text28';
            app.text28.Text = 'Vectors';
            app.text31 = uilabel(app.GridLayout30);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Planes';
            app.vectorsToPlotListbox = uilistbox(app.GridLayout30);
            app.vectorsToPlotListbox.Items = {'Listbox'};
            app.vectorsToPlotListbox.Multiselect = 'on';
            app.vectorsToPlotListbox.ValueChangedFcn = createCallbackFcn(app, @vectorsToPlotListbox_Callback, true);
            app.vectorsToPlotListbox.Tag = 'vectorsToPlotListbox';
            app.vectorsToPlotListbox.Value = {'Listbox'};
            app.planesToPlotListbox = uilistbox(app.GridLayout30);
            app.planesToPlotListbox.Items = {'Listbox'};
            app.planesToPlotListbox.Multiselect = 'on';
            app.planesToPlotListbox.ValueChangedFcn = createCallbackFcn(app, @planesToPlotListbox_Callback, true);
            app.planesToPlotListbox.Tag = 'planesToPlotListbox';
            app.planesToPlotListbox.Value = {'Listbox'};
            app.text29 = uilabel(app.GridLayout30);
            app.text29.Tag = 'text29';
            app.text29.Text = 'Reference Frames';
            app.refFramessToPlotListbox = uilistbox(app.GridLayout30);
            app.refFramessToPlotListbox.Items = {'Listbox'};
            app.refFramessToPlotListbox.Multiselect = 'on';
            app.refFramessToPlotListbox.ValueChangedFcn = createCallbackFcn(app, @refFramessToPlotListbox_Callback, true);
            app.refFramessToPlotListbox.Tag = 'refFramessToPlotListbox';
            app.refFramessToPlotListbox.Value = {'Listbox'};
            app.GeometricPointsGroundTrackDisplayPanel = uipanel(app.GridLayout30);
            app.GeometricPointsGroundTrackDisplayPanel.Title = 'Geometric Points Ground Track Display';
            app.GridLayout43_3 = uigridlayout(app.GeometricPointsGroundTrackDisplayPanel);
            app.ShowGeometricPointsonGroundTrackCheckBox = uicheckbox(app.GridLayout43_3);
            app.ShowGeometricPointsonGroundTrackCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowGeometricPointsonGroundTrackCheckBoxValueChanged, true);
            app.ShowGeometricPointsonGroundTrackCheckBox.Text = 'Show Geometric Points on Ground Track';
            app.SensorsTab = uitab(app.TabGroup);
            app.SensorsTab.Title = 'Sensors';
            app.GridLayout31 = uigridlayout(app.SensorsTab);
            app.uipanel15_2 = uipanel(app.GridLayout31);
            app.uipanel15_2.Title = 'Sensors to Plot';
            app.uipanel15_2.Tag = 'uipanel15';
            app.GridLayout32 = uigridlayout(app.uipanel15_2);
            app.text27_2 = uilabel(app.GridLayout32);
            app.text27_2.Tag = 'text27';
            app.text27_2.Text = 'Sensors';
            app.text30_2 = uilabel(app.GridLayout32);
            app.text30_2.Tag = 'text30';
            app.text30_2.Text = 'Sensor Targets';
            app.sensorsToPlotListbox = uilistbox(app.GridLayout32);
            app.sensorsToPlotListbox.Items = {'Listbox'};
            app.sensorsToPlotListbox.Multiselect = 'on';
            app.sensorsToPlotListbox.ValueChangedFcn = createCallbackFcn(app, @sensorsToPlotListboxValueChanged, true);
            app.sensorsToPlotListbox.Tag = 'sensorsToPlotListbox';
            app.sensorsToPlotListbox.Tooltip = {''};
            app.sensorsToPlotListbox.Value = {'Listbox'};
            app.sensorTgtsToPlotListbox = uilistbox(app.GridLayout32);
            app.sensorTgtsToPlotListbox.Items = {'Listbox'};
            app.sensorTgtsToPlotListbox.Multiselect = 'on';
            app.sensorTgtsToPlotListbox.ValueChangedFcn = createCallbackFcn(app, @sensorTgtsToPlotListboxValueChanged, true);
            app.sensorTgtsToPlotListbox.Tag = 'sensorTgtsToPlotListbox';
            app.sensorTgtsToPlotListbox.Value = {'Listbox'};
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            % Show the figure after all components are created
            app.lvd_viewSettingsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_viewSettingsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_viewSettingsGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_viewSettingsGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_viewSettingsGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_viewSettingsGUI)
        end
    end
end