classdef ma_AstroCalculatorsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ma_AstroCalulatorGUI           matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup2                      matlab.ui.container.TabGroup
        SMAPeriodTab                   matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        computePeriodFromSmaButton     matlab.ui.control.Button
        computeSmaFromPeriodButton     matlab.ui.control.Button
        periodSmaText                  matlab.ui.control.EditField
        text4                          matlab.ui.control.Label
        text3                          matlab.ui.control.Label
        smaPeriodText                  matlab.ui.control.EditField
        SMAEccRaRpTab                  matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        text9                          matlab.ui.control.Label
        RpFromSmaEccText               matlab.ui.control.EditField
        computeRaRpFromSmaEccButton    matlab.ui.control.Button
        text7                          matlab.ui.control.Label
        eccFromRpRaText                matlab.ui.control.EditField
        text6                          matlab.ui.control.Label
        RaFromSmaEccText               matlab.ui.control.EditField
        computeSmaEccFromRaRpButton    matlab.ui.control.Button
        text5                          matlab.ui.control.Label
        smaFromRpRaText                matlab.ui.control.EditField
        SMAEccTARVFPATab               matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        text25                         matlab.ui.control.Label
        text24                         matlab.ui.control.Label
        text23                         matlab.ui.control.Label
        fpaSmaEccTruText               matlab.ui.control.EditField
        vSmaEccTruText                 matlab.ui.control.EditField
        rSmaEccTruText                 matlab.ui.control.EditField
        text22                         matlab.ui.control.Label
        truRVPathText                  matlab.ui.control.EditField
        text21                         matlab.ui.control.Label
        eccRVPathText                  matlab.ui.control.EditField
        computeRVFpaButton             matlab.ui.control.Button
        computeSmaEccTruButton         matlab.ui.control.Button
        text20                         matlab.ui.control.Label
        smaRVPathText                  matlab.ui.control.EditField
        TAGEOLongTab                   matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        text14                         matlab.ui.control.Label
        epochTrueAnomText              matlab.ui.control.EditField
        text16                         matlab.ui.control.Label
        longFromTrueAnomText           matlab.ui.control.EditField
        computeTrueAnomalyFromLongButton  matlab.ui.control.Button
        computeLongFromTrueAnomButton  matlab.ui.control.Button
        text15                         matlab.ui.control.Label
        trueAnomFromLongText           matlab.ui.control.EditField
        centralBodyCombo               matlab.ui.control.DropDown
        text2                          matlab.ui.control.Label
    end

    methods (Access = private)
        function setUpGui(app, handles)
            celBodyData = getappdata(handles.ma_AstroCalulatorGUI,'celBodyData');
            populateBodiesCombo(celBodyData, handles.centralBodyCombo);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ma_AstroCalulatorGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to ma_AstroCalulatorGUI (see VARARGIN)

            % Choose default command line output for ma_AstroCalulatorGUI
            handles.output = hObject;

            centerUIFigure(app.ma_AstroCalulatorGUI);
            applySelectedThemeToApp(app);

            % Update handles structure
            guidata(hObject, handles);

            celBodyData = varargin{1};
            setappdata(hObject,'celBodyData',celBodyData);

            guidata(hObject, handles);

            setUpGui(app, handles);
        end

        % Value changed function: RaFromSmaEccText
        function RaFromSmaEccText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to RaFromSmaEccText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of RaFromSmaEccText as text
            %        str2double(get(hObject,'String')) returns contents of RaFromSmaEccText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: RpFromSmaEccText
        function RpFromSmaEccText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to RpFromSmaEccText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of RpFromSmaEccText as text
            %        str2double(get(hObject,'String')) returns contents of RpFromSmaEccText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Button pushed function: computeLongFromTrueAnomButton
        function computeLongFromTrueAnomButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to computeLongFromTrueAnomButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = {};

            value = str2double(get(handles.epochTrueAnomText,'String'));
            enteredStr = get(handles.epochTrueAnomText,'String');
            numberName = 'Epoch of True Anomaly';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(get(handles.trueAnomFromLongText,'String'));
            enteredStr = get(handles.trueAnomFromLongText,'String');
            numberName = 'True Anomay';
            lb = -360;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                celBodyData = getappdata(handles.ma_AstroCalulatorGUI,'celBodyData');
                contents = cellstr(get(handles.centralBodyCombo,'String'));
                sel = strtrim(lower(contents{get(handles.centralBodyCombo,'Value')}));
                bodyInfo = celBodyData.(sel);

                ut = str2double(get(handles.epochTrueAnomText,'String'));
                tru = deg2rad(str2double(get(handles.trueAnomFromLongText,'String')));
                syncSMA = computeSMAFromPeriod(bodyInfo.rotperiod, bodyInfo.gm);
                [rVectECI,vVectECI]=getStatefromKepler(syncSMA, 0.0, 0.0, 0.0, 0.0, tru, bodyInfo.gm, true);
                [~, long, ~] = getLatLongAltFromInertialVect(ut, rVectECI, bodyInfo, vVectECI);
                long = rad2deg(long);

                set(handles.longFromTrueAnomText,'String',sprintf('%0.5f',long));
            else
                msgbox(errMsg,'Errors were found computing SMA.','error');
            end
        end

        % Button pushed function: computePeriodFromSmaButton
        function computePeriodFromSmaButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to computePeriodFromSmaButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = {};

            value = str2double(get(handles.smaPeriodText,'String'));
            enteredStr = get(handles.smaPeriodText,'String');
            numberName = 'Semi-major Axis';
            lb = 0.001;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                celBodyData = getappdata(handles.ma_AstroCalulatorGUI,'celBodyData');
                contents = cellstr(get(handles.centralBodyCombo,'String'));
                sel = strtrim(lower(contents{get(handles.centralBodyCombo,'Value')}));
                bodyInfo = celBodyData.(sel);

                sma = str2double(get(handles.smaPeriodText,'String'));
                gmu = bodyInfo.gm;

                sma = computePeriod(sma, gmu);
                set(handles.periodSmaText,'String',sprintf('%0.5f',sma));
            else
                msgbox(errMsg,'Errors were found computing SMA.','error');
            end
        end

        % Button pushed function: computeRVFpaButton
        function computeRVFpaButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to computeRVFpaButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = {};

            ecc = str2double(get(handles.eccRVPathText,'String'));
            enteredStr = get(handles.eccRVPathText,'String');
            numberName = 'Eccentricity';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(ecc, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(ecc < 1)
                sma = str2double(get(handles.smaRVPathText,'String'));
                enteredStr = get(handles.smaRVPathText,'String');
                numberName = 'Semi-major Axis';
                lb = 0.001;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(sma, numberName, lb, ub, isInt, errMsg, enteredStr);
            else
                sma = str2double(get(handles.smaRVPathText,'String'));
                enteredStr = get(handles.smaRVPathText,'String');
                numberName = 'Semi-major Axis';
                lb = -Inf;
                ub = -0.001;
                isInt = false;
                errMsg = validateNumber(sma, numberName, lb, ub, isInt, errMsg, enteredStr);
            end

            tru = str2double(get(handles.truRVPathText,'String'));
            enteredStr = get(handles.truRVPathText,'String');
            numberName = 'True Anomay';
            lb = -360;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(tru, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                celBodyData = getappdata(handles.ma_AstroCalulatorGUI,'celBodyData');
                contents = cellstr(get(handles.centralBodyCombo,'String'));
                sel = strtrim(lower(contents{get(handles.centralBodyCombo,'Value')}));
                bodyInfo = celBodyData.(sel);

                tru = deg2rad(tru);

                [radius, velocity, fpa] = computeRVFpaFromSmaEccTru(sma, ecc, tru, bodyInfo.gm);

                handles.rSmaEccTruText.String = fullAccNum2Str(radius);
                handles.vSmaEccTruText.String = fullAccNum2Str(velocity);
                handles.fpaSmaEccTruText.String = fullAccNum2Str(rad2deg(fpa));
            else
                msgbox(errMsg,'Errors were found computing R/V/FPA.','error');
            end
        end

        % Button pushed function: computeRaRpFromSmaEccButton
        function computeRaRpFromSmaEccButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to computeRaRpFromSmaEccButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = {};

            eccValue = str2double(get(handles.eccFromRpRaText,'String'));
            enteredStr = get(handles.eccFromRpRaText,'String');
            numberName = 'Radius of Periapsis';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(eccValue, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(eccValue < 1)
                value = str2double(get(handles.smaFromRpRaText,'String'));
                enteredStr = get(handles.smaFromRpRaText,'String');
                numberName = 'Semi-major Axis';
                lb = 0.001;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);
            else
                value = str2double(get(handles.smaFromRpRaText,'String'));
                enteredStr = get(handles.smaFromRpRaText,'String');
                numberName = 'Semi-major Axis';
                lb = -Inf;
                ub = -0.001;
                isInt = false;
                errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);
            end

            if(isempty(errMsg))
                sma = str2double(get(handles.smaFromRpRaText,'String'));
                ecc = str2double(get(handles.eccFromRpRaText,'String'));

                [rAp, rPe] = computeApogeePerigee(sma, ecc);
                set(handles.RaFromSmaEccText,'String',sprintf('%0.5f',rAp));
                set(handles.RpFromSmaEccText,'String',sprintf('%0.5f',rPe));
            else
                msgbox(errMsg,'Errors were found computing SMA.','error');
            end
        end

        % Button pushed function: computeSmaEccFromRaRpButton
        function computeSmaEccFromRaRpButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to computeSmaEccFromRaRpButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = {};

            value = str2double(get(handles.RaFromSmaEccText,'String'));
            enteredStr = get(handles.RaFromSmaEccText,'String');
            numberName = 'Radius of Apoapsis';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(get(handles.RpFromSmaEccText,'String'));
            enteredStr = get(handles.RpFromSmaEccText,'String');
            numberName = 'Radius of Periapsis';
            lb = 0.001;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                rA = str2double(get(handles.RaFromSmaEccText,'String'));
                rP = str2double(get(handles.RpFromSmaEccText,'String'));

                [sma, ecc] =  computeSmaEccFromRaRp(rA, rP);
                set(handles.smaFromRpRaText,'String',sprintf('%0.5f',sma));
                set(handles.eccFromRpRaText,'String',sprintf('%0.8f',ecc));
            else
                msgbox(errMsg,'Errors were found computing SMA.','error');
            end
        end

        % Button pushed function: computeSmaEccTruButton
        function computeSmaEccTruButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to computeSmaEccTruButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = {};

            radius = str2double(get(handles.rSmaEccTruText,'String'));
            enteredStr = get(handles.rSmaEccTruText,'String');
            numberName = 'Spacecraft Radius';
            lb = 0.001;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(radius, numberName, lb, ub, isInt, errMsg, enteredStr);

            velocity = str2double(get(handles.vSmaEccTruText,'String'));
            enteredStr = get(handles.vSmaEccTruText,'String');
            numberName = 'Spacecraft Velocity';
            lb = 0.001;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(velocity, numberName, lb, ub, isInt, errMsg, enteredStr);

            fpa = str2double(get(handles.fpaSmaEccTruText,'String'));
            enteredStr = get(handles.fpaSmaEccTruText,'String');
            numberName = 'True Anomay';
            lb = -90;
            ub = 90;
            isInt = false;
            errMsg = validateNumber(fpa, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                celBodyData = getappdata(handles.ma_AstroCalulatorGUI,'celBodyData');
                contents = cellstr(get(handles.centralBodyCombo,'String'));
                sel = strtrim(lower(contents{get(handles.centralBodyCombo,'Value')}));
                bodyInfo = celBodyData.(sel);

                fpa = deg2rad(fpa);

                [sma, ecc, tru] = computeSmaEccTruFromRVFpa(radius, velocity, fpa, bodyInfo.gm);

                handles.smaRVPathText.String = fullAccNum2Str(sma);
                handles.eccRVPathText.String = fullAccNum2Str(ecc);
                handles.truRVPathText.String = fullAccNum2Str(rad2deg(tru));
            else
                msgbox(errMsg,'Errors were found computing SMA/Ecc/True Anomaly.','error');
            end
        end

        % Button pushed function: computeSmaFromPeriodButton
        function computeSmaFromPeriodButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to computeSmaFromPeriodButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = {};

            value = str2double(get(handles.periodSmaText,'String'));
            enteredStr = get(handles.periodSmaText,'String');
            numberName = 'Orbital Period';
            lb = 0.001;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                celBodyData = getappdata(handles.ma_AstroCalulatorGUI,'celBodyData');
                contents = cellstr(get(handles.centralBodyCombo,'String'));
                sel = strtrim(lower(contents{get(handles.centralBodyCombo,'Value')}));
                bodyInfo = celBodyData.(sel);

                period = str2double(get(handles.periodSmaText,'String'));
                gmu = bodyInfo.gm;

                sma = computeSMAFromPeriod(period, gmu);
                set(handles.smaPeriodText,'String',sprintf('%0.5f',sma));
            else
                msgbox(errMsg,'Errors were found computing SMA.','error');
            end
        end

        % Button pushed function: computeTrueAnomalyFromLongButton
        function computeTrueAnomalyFromLongButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to computeTrueAnomalyFromLongButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = {};

            value = str2double(get(handles.epochTrueAnomText,'String'));
            enteredStr = get(handles.epochTrueAnomText,'String');
            numberName = 'Epoch of True Anomaly';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(get(handles.longFromTrueAnomText,'String'));
            enteredStr = get(handles.longFromTrueAnomText,'String');
            numberName = 'Longitude';
            lb = -360;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                celBodyData = getappdata(handles.ma_AstroCalulatorGUI,'celBodyData');
                contents = cellstr(get(handles.centralBodyCombo,'String'));
                sel = strtrim(lower(contents{get(handles.centralBodyCombo,'Value')}));
                bodyInfo = celBodyData.(sel);

                ut = str2double(get(handles.epochTrueAnomText,'String'));
                long = deg2rad(str2double(get(handles.longFromTrueAnomText,'String')));
                syncSMA = computeSMAFromPeriod(bodyInfo.rotperiod, bodyInfo.gm);
                rVectECI = getInertialVectFromLatLongAlt(ut, 0.0, long, syncSMA, bodyInfo, [NaN;NaN;NaN]);
                tru = dang(rVectECI,[1;0;0]);
                if(rVectECI(2) < 0)
                    tru = 2*pi - tru;
                end
                tru = rad2deg(tru);

                set(handles.trueAnomFromLongText,'String',sprintf('%0.5f',tru));
            else
                msgbox(errMsg,'Errors were found computing SMA.','error');
            end
        end

        % Value changed function: eccFromRpRaText
        function eccFromRpRaText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to eccFromRpRaText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of eccFromRpRaText as text
            %        str2double(get(hObject,'String')) returns contents of eccFromRpRaText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: eccRVPathText
        function eccRVPathText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to eccRVPathText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of eccRVPathText as text
            %        str2double(get(hObject,'String')) returns contents of eccRVPathText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: epochTrueAnomText
        function epochTrueAnomText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to epochTrueAnomText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of epochTrueAnomText as text
            %        str2double(get(hObject,'String')) returns contents of epochTrueAnomText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: fpaSmaEccTruText
        function fpaSmaEccTruText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to fpaSmaEccTruText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of fpaSmaEccTruText as text
            %        str2double(get(hObject,'String')) returns contents of fpaSmaEccTruText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: longFromTrueAnomText
        function longFromTrueAnomText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to longFromTrueAnomText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of longFromTrueAnomText as text
            %        str2double(get(hObject,'String')) returns contents of longFromTrueAnomText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: periodSmaText
        function periodSmaText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to periodSmaText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of periodSmaText as text
            %        str2double(get(hObject,'String')) returns contents of periodSmaText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: rSmaEccTruText
        function rSmaEccTruText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to rSmaEccTruText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of rSmaEccTruText as text
            %        str2double(get(hObject,'String')) returns contents of rSmaEccTruText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: smaFromRpRaText
        function smaFromRpRaText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to smaFromRpRaText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of smaFromRpRaText as text
            %        str2double(get(hObject,'String')) returns contents of smaFromRpRaText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: smaPeriodText
        function smaPeriodText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to smaPeriodText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of smaPeriodText as text
            %        str2double(get(hObject,'String')) returns contents of smaPeriodText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: smaRVPathText
        function smaRVPathText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to smaRVPathText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of smaRVPathText as text
            %        str2double(get(hObject,'String')) returns contents of smaRVPathText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: truRVPathText
        function truRVPathText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to truRVPathText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of truRVPathText as text
            %        str2double(get(hObject,'String')) returns contents of truRVPathText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: trueAnomFromLongText
        function trueAnomFromLongText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to trueAnomFromLongText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of trueAnomFromLongText as text
            %        str2double(get(hObject,'String')) returns contents of trueAnomFromLongText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: vSmaEccTruText
        function vSmaEccTruText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to vSmaEccTruText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of vSmaEccTruText as text
            %        str2double(get(hObject,'String')) returns contents of vSmaEccTruText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ma_AstroCalulatorGUI = uifigure('Visible', 'off');
            app.ma_AstroCalulatorGUI.Name = 'Astrodynamics Calculators';
            app.ma_AstroCalulatorGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.ma_AstroCalulatorGUI.Tag = 'ma_AstroCalulatorGUI';
            app.GridLayout = uigridlayout(app.ma_AstroCalulatorGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Astrodynamics Calculators';
            app.centralBodyCombo = uidropdown(app.GridLayout);
            app.centralBodyCombo.Items = {'Pop-up Menu'};
            app.centralBodyCombo.Tag = 'centralBodyCombo';
            app.centralBodyCombo.Value = 'Pop-up Menu';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.SMAPeriodTab = uitab(app.TabGroup2);
            app.SMAPeriodTab.Tooltip = {'Convert between SMA and orbital period.'};
            app.SMAPeriodTab.Title = 'SMA<->Period';
            app.GridLayout2 = uigridlayout(app.SMAPeriodTab);
            app.smaPeriodText = uieditfield(app.GridLayout2, 'text');
            app.smaPeriodText.ValueChangedFcn = createCallbackFcn(app, @smaPeriodText_Callback, true);
            app.smaPeriodText.Tag = 'smaPeriodText';
            app.smaPeriodText.Tooltip = 'Semi-major Axis';
            app.text3 = uilabel(app.GridLayout2);
            app.text3.Tag = 'text3';
            app.text3.Text = 'km';
            app.text4 = uilabel(app.GridLayout2);
            app.text4.Tag = 'text4';
            app.text4.Text = 'sec';
            app.periodSmaText = uieditfield(app.GridLayout2, 'text');
            app.periodSmaText.ValueChangedFcn = createCallbackFcn(app, @periodSmaText_Callback, true);
            app.periodSmaText.Tag = 'periodSmaText';
            app.periodSmaText.Tooltip = 'Orbital Period';
            app.computeSmaFromPeriodButton = uibutton(app.GridLayout2, 'push');
            app.computeSmaFromPeriodButton.ButtonPushedFcn = createCallbackFcn(app, @computeSmaFromPeriodButton_Callback, true);
            app.computeSmaFromPeriodButton.Tag = 'computeSmaFromPeriodButton';
            app.computeSmaFromPeriodButton.Icon = 'left-arrow.png';
            app.computeSmaFromPeriodButton.Tooltip = 'Compute SMA From Period';
            app.computeSmaFromPeriodButton.Text = '';
            app.computePeriodFromSmaButton = uibutton(app.GridLayout2, 'push');
            app.computePeriodFromSmaButton.ButtonPushedFcn = createCallbackFcn(app, @computePeriodFromSmaButton_Callback, true);
            app.computePeriodFromSmaButton.Tag = 'computePeriodFromSmaButton';
            app.computePeriodFromSmaButton.Icon = 'right-arrow.png';
            app.computePeriodFromSmaButton.Tooltip = 'Compute Period From SMA';
            app.computePeriodFromSmaButton.Text = '';
            app.SMAEccRaRpTab = uitab(app.TabGroup2);
            app.SMAEccRaRpTab.Tooltip = {'Convert between SMA + eccentricity and radius of apoapsis + periapsis. '};
            app.SMAEccRaRpTab.Title = 'SMA/Ecc <-> Ra/Rp';
            app.GridLayout3 = uigridlayout(app.SMAEccRaRpTab);
            app.smaFromRpRaText = uieditfield(app.GridLayout3, 'text');
            app.smaFromRpRaText.ValueChangedFcn = createCallbackFcn(app, @smaFromRpRaText_Callback, true);
            app.smaFromRpRaText.Tag = 'smaFromRpRaText';
            app.smaFromRpRaText.Tooltip = 'Semi-major Axis';
            app.text5 = uilabel(app.GridLayout3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'km';
            app.computeSmaEccFromRaRpButton = uibutton(app.GridLayout3, 'push');
            app.computeSmaEccFromRaRpButton.ButtonPushedFcn = createCallbackFcn(app, @computeSmaEccFromRaRpButton_Callback, true);
            app.computeSmaEccFromRaRpButton.Tag = 'computeSmaEccFromRaRpButton';
            app.computeSmaEccFromRaRpButton.Icon = 'left-arrow.png';
            app.computeSmaEccFromRaRpButton.Tooltip = 'Compute SMA/Ecc From Ra/Rp';
            app.computeSmaEccFromRaRpButton.Text = '';
            app.RaFromSmaEccText = uieditfield(app.GridLayout3, 'text');
            app.RaFromSmaEccText.ValueChangedFcn = createCallbackFcn(app, @RaFromSmaEccText_Callback, true);
            app.RaFromSmaEccText.Tag = 'RaFromSmaEccText';
            app.RaFromSmaEccText.Tooltip = 'Radius of Apoapsis';
            app.text6 = uilabel(app.GridLayout3);
            app.text6.Tag = 'text6';
            app.text6.Text = 'km';
            app.eccFromRpRaText = uieditfield(app.GridLayout3, 'text');
            app.eccFromRpRaText.ValueChangedFcn = createCallbackFcn(app, @eccFromRpRaText_Callback, true);
            app.eccFromRpRaText.Tag = 'eccFromRpRaText';
            app.eccFromRpRaText.Tooltip = 'Eccentricity';
            app.text7 = uilabel(app.GridLayout3);
            app.text7.Tag = 'text7';
            app.text7.Text = '';
            app.computeRaRpFromSmaEccButton = uibutton(app.GridLayout3, 'push');
            app.computeRaRpFromSmaEccButton.ButtonPushedFcn = createCallbackFcn(app, @computeRaRpFromSmaEccButton_Callback, true);
            app.computeRaRpFromSmaEccButton.Tag = 'computeRaRpFromSmaEccButton';
            app.computeRaRpFromSmaEccButton.Icon = 'right-arrow.png';
            app.computeRaRpFromSmaEccButton.Tooltip = 'Compute Ra/Rp From SMA/Ecc';
            app.computeRaRpFromSmaEccButton.Text = '';
            app.RpFromSmaEccText = uieditfield(app.GridLayout3, 'text');
            app.RpFromSmaEccText.ValueChangedFcn = createCallbackFcn(app, @RpFromSmaEccText_Callback, true);
            app.RpFromSmaEccText.Tag = 'RpFromSmaEccText';
            app.RpFromSmaEccText.Tooltip = 'Radius of Periapsis';
            app.text9 = uilabel(app.GridLayout3);
            app.text9.Tag = 'text9';
            app.text9.Text = 'km';
            app.SMAEccTARVFPATab = uitab(app.TabGroup2);
            app.SMAEccTARVFPATab.Tooltip = {'Convert between SMA/eccentricity/true anomaly and radius vector length, velocity vector magnitude, and flight path angle.'};
            app.SMAEccTARVFPATab.Title = 'SMA/Ecc/TA <-> R/V/FPA';
            app.GridLayout4 = uigridlayout(app.SMAEccTARVFPATab);
            app.smaRVPathText = uieditfield(app.GridLayout4, 'text');
            app.smaRVPathText.ValueChangedFcn = createCallbackFcn(app, @smaRVPathText_Callback, true);
            app.smaRVPathText.Tag = 'smaRVPathText';
            app.smaRVPathText.Tooltip = 'Semi-major Axis';
            app.text20 = uilabel(app.GridLayout4);
            app.text20.Tag = 'text20';
            app.text20.Text = 'km';
            app.computeSmaEccTruButton = uibutton(app.GridLayout4, 'push');
            app.computeSmaEccTruButton.ButtonPushedFcn = createCallbackFcn(app, @computeSmaEccTruButton_Callback, true);
            app.computeSmaEccTruButton.Tag = 'computeSmaEccTruButton';
            app.computeSmaEccTruButton.Icon = 'left-arrow.png';
            app.computeSmaEccTruButton.Tooltip = 'Compute SMA/Ecc/True Anomaly';
            app.computeSmaEccTruButton.Text = '';
            app.computeRVFpaButton = uibutton(app.GridLayout4, 'push');
            app.computeRVFpaButton.ButtonPushedFcn = createCallbackFcn(app, @computeRVFpaButton_Callback, true);
            app.computeRVFpaButton.Tag = 'computeRVFpaButton';
            app.computeRVFpaButton.Icon = 'right-arrow.png';
            app.computeRVFpaButton.Tooltip = 'Compute Radius/Velocity/Flight Path Angle';
            app.computeRVFpaButton.Text = '';
            app.eccRVPathText = uieditfield(app.GridLayout4, 'text');
            app.eccRVPathText.ValueChangedFcn = createCallbackFcn(app, @eccRVPathText_Callback, true);
            app.eccRVPathText.Tag = 'eccRVPathText';
            app.eccRVPathText.Tooltip = 'Eccentricity';
            app.text21 = uilabel(app.GridLayout4);
            app.text21.Tag = 'text21';
            app.text21.Text = '';
            app.truRVPathText = uieditfield(app.GridLayout4, 'text');
            app.truRVPathText.ValueChangedFcn = createCallbackFcn(app, @truRVPathText_Callback, true);
            app.truRVPathText.Tag = 'truRVPathText';
            app.truRVPathText.Tooltip = 'True Anomaly';
            app.text22 = uilabel(app.GridLayout4);
            app.text22.Tag = 'text22';
            app.text22.Text = 'deg';
            app.rSmaEccTruText = uieditfield(app.GridLayout4, 'text');
            app.rSmaEccTruText.ValueChangedFcn = createCallbackFcn(app, @rSmaEccTruText_Callback, true);
            app.rSmaEccTruText.Tag = 'rSmaEccTruText';
            app.rSmaEccTruText.Tooltip = 'Spacecraft Radius';
            app.vSmaEccTruText = uieditfield(app.GridLayout4, 'text');
            app.vSmaEccTruText.ValueChangedFcn = createCallbackFcn(app, @vSmaEccTruText_Callback, true);
            app.vSmaEccTruText.Tag = 'vSmaEccTruText';
            app.vSmaEccTruText.Tooltip = 'Spacecraft Velocity';
            app.fpaSmaEccTruText = uieditfield(app.GridLayout4, 'text');
            app.fpaSmaEccTruText.ValueChangedFcn = createCallbackFcn(app, @fpaSmaEccTruText_Callback, true);
            app.fpaSmaEccTruText.Tag = 'fpaSmaEccTruText';
            app.fpaSmaEccTruText.Tooltip = 'Flight Path Angle';
            app.text23 = uilabel(app.GridLayout4);
            app.text23.Tag = 'text23';
            app.text23.Text = 'km';
            app.text24 = uilabel(app.GridLayout4);
            app.text24.Tag = 'text24';
            app.text24.Text = 'km/s';
            app.text25 = uilabel(app.GridLayout4);
            app.text25.Tag = 'text25';
            app.text25.Text = 'deg';
            app.TAGEOLongTab = uitab(app.TabGroup2);
            app.TAGEOLongTab.Tooltip = {'Convert true anomaly in geostationary orbit to longitude. '};
            app.TAGEOLongTab.Title = 'TA <-> GEO Long.';
            app.GridLayout5 = uigridlayout(app.TAGEOLongTab);
            app.trueAnomFromLongText = uieditfield(app.GridLayout5, 'text');
            app.trueAnomFromLongText.ValueChangedFcn = createCallbackFcn(app, @trueAnomFromLongText_Callback, true);
            app.trueAnomFromLongText.Tag = 'trueAnomFromLongText';
            app.trueAnomFromLongText.Tooltip = 'True Anomaly at Epoch';
            app.text15 = uilabel(app.GridLayout5);
            app.text15.Tag = 'text15';
            app.text15.Text = 'deg';
            app.computeLongFromTrueAnomButton = uibutton(app.GridLayout5, 'push');
            app.computeLongFromTrueAnomButton.ButtonPushedFcn = createCallbackFcn(app, @computeLongFromTrueAnomButton_Callback, true);
            app.computeLongFromTrueAnomButton.Tag = 'computeLongFromTrueAnomButton';
            app.computeLongFromTrueAnomButton.Icon = 'right-arrow.png';
            app.computeLongFromTrueAnomButton.Tooltip = 'Compute Ra/Rp From SMA/Ecc';
            app.computeLongFromTrueAnomButton.Text = '';
            app.computeTrueAnomalyFromLongButton = uibutton(app.GridLayout5, 'push');
            app.computeTrueAnomalyFromLongButton.ButtonPushedFcn = createCallbackFcn(app, @computeTrueAnomalyFromLongButton_Callback, true);
            app.computeTrueAnomalyFromLongButton.Tag = 'computeTrueAnomalyFromLongButton';
            app.computeTrueAnomalyFromLongButton.Icon = 'left-arrow.png';
            app.computeTrueAnomalyFromLongButton.Tooltip = 'Compute SMA/Ecc From Ra/Rp';
            app.computeTrueAnomalyFromLongButton.Text = '';
            app.longFromTrueAnomText = uieditfield(app.GridLayout5, 'text');
            app.longFromTrueAnomText.ValueChangedFcn = createCallbackFcn(app, @longFromTrueAnomText_Callback, true);
            app.longFromTrueAnomText.Tag = 'longFromTrueAnomText';
            app.longFromTrueAnomText.Tooltip = 'Longitude at Epoch';
            app.text16 = uilabel(app.GridLayout5);
            app.text16.Tag = 'text16';
            app.text16.Text = 'deg';
            app.epochTrueAnomText = uieditfield(app.GridLayout5, 'text');
            app.epochTrueAnomText.ValueChangedFcn = createCallbackFcn(app, @epochTrueAnomText_Callback, true);
            app.epochTrueAnomText.Tag = 'epochTrueAnomText';
            app.epochTrueAnomText.Tooltip = 'Epoch of True Anomaly';
            app.text14 = uilabel(app.GridLayout5);
            app.text14.Tag = 'text14';
            app.text14.Text = 'sec';
            % Show the figure after all components are created
            app.ma_AstroCalulatorGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ma_AstroCalculatorsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ma_AstroCalulatorGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)ma_AstroCalulatorGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ma_AstroCalulatorGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ma_AstroCalulatorGUI)
        end
    end
end