classdef ma_CelBodyCatalogGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ma_CelBodyCatalogGUI  matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        GridLayout2           matlab.ui.container.GridLayout
        GridLayout3           matlab.ui.container.GridLayout
        dispAxesButtonGrp     matlab.ui.container.ButtonGroup
        displayAtmoRadio      matlab.ui.control.RadioButton
        displayOrbitRadio     matlab.ui.control.RadioButton
        displayBodyRadio      matlab.ui.control.RadioButton
        uipanel1              matlab.ui.container.Panel
        GridLayout4           matlab.ui.container.GridLayout
        infoLabel             matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        GridLayout5           matlab.ui.container.GridLayout
        dispAxes              matlab.ui.control.UIAxes
        CatalogBodiesTree     matlab.ui.container.Tree
        text1                 matlab.ui.control.Label
    end


    methods (Access = private)
        function bodyInfoStr = generateBodyInfoStr(~, bodyInfo, celBodyData)
            hrule = '-----------------------------------';

            [year, day, hour, minute, sec] = convertSec2YearDayHrMnSec(bodyInfo.epoch);
            [dateStr] = formDateStr(year, day, hour, minute, sec);

            parentBodyInfo = bodyInfo.getParBodyInfo(celBodyData);
            if(bodyInfo.sma > 0 && ~isempty(parentBodyInfo))
                [~, ~, secInDay, ~] = getSecondsInVariousTimeUnits();
                period = computePeriod(bodyInfo.sma, getParentGM(bodyInfo, celBodyData));
                periodStr = [num2str(period/secInDay), ' days'];

                [rAp, rPe] = computeApogeePerigee(bodyInfo.sma, bodyInfo.ecc);
                rApStr = [num2str(rAp), ' km'];
                rPeStr = [num2str(rPe), ' km'];
            else
                periodStr = 'N/A';

                [~, rPe] = computeApogeePerigee(bodyInfo.sma, bodyInfo.ecc);
                rApStr = 'N/A';
                rPeStr = [num2str(rPe), ' km'];
            end

            if(bodyInfo.sma == 0)
                rPeStr = 'N/A';
            end

            if(isempty(bodyInfo.parent))
                parentStr = 'N/A';
                rSOIStr = 'N/A';
                rSOI = Inf;
            else
                parentStr = bodyInfo.parent;

                rSOI = getSOIRadius(bodyInfo, parentBodyInfo);
                rSOIStr = [num2str(rSOI), ' km'];
            end

            sPhr = floor(bodyInfo.rotperiod/3600);
            sPmin = floor((bodyInfo.rotperiod - 3600*sPhr)/60);
            sPsec = bodyInfo.rotperiod - 3600*sPhr - 60*sPmin;
            sPStr = sprintf('%02.0f:%02.0f:%07.4f',sPhr,sPmin,sPsec);

            syncSMA = computeSMAFromPeriod(bodyInfo.rotperiod, bodyInfo.gm);
            if(syncSMA > rSOI)
                syncNotPoss = '*';
            else
                syncNotPoss = '';
            end

            bodyInfoStr{1} = ['Name: ', bodyInfo.name];
            bodyInfoStr{end+1} = ['ID: ', num2str(bodyInfo.id)];
            bodyInfoStr{end+1} = ['Parent: ', parentStr];
            bodyInfoStr{end+1} = hrule;

            bodyInfoStr{end+1} = 'Orbit';
            bodyInfoStr{end+1} = [' Epoch = ', dateStr];
            bodyInfoStr{end+1} = [' SMA = ', num2str(bodyInfo.sma), ' km'];
            bodyInfoStr{end+1} = [' Ecc = ', num2str(bodyInfo.ecc)];
            bodyInfoStr{end+1} = [' Inc = ', num2str(bodyInfo.inc), ' deg'];
            bodyInfoStr{end+1} = [' RAAN = ', num2str(bodyInfo.raan), ' deg'];
            bodyInfoStr{end+1} = [' Arg = ', num2str(bodyInfo.arg), ' deg'];
            bodyInfoStr{end+1} = [' Mean = ', num2str(bodyInfo.mean), ' deg'];
            bodyInfoStr{end+1} = hrule;

            bodyInfoStr{end+1} = 'Physical Properties';
            bodyInfoStr{end+1} = [' GM = ', num2str(bodyInfo.gm), ' km^3/s^2'];
            bodyInfoStr{end+1} = [' Radius = ', num2str(bodyInfo.radius), ' km'];
            bodyInfoStr{end+1} = [' Atmo Height = ', num2str(bodyInfo.atmohgt), ' km'];
            bodyInfoStr{end+1} = [' Atmo SL Press = ', num2str(getPressureAtAltitude(bodyInfo, 0)), ' kPa'];
            bodyInfoStr{end+1} = [' Sidereal Day = ', num2str(bodyInfo.rotperiod), ' sec'];
            bodyInfoStr{end+1} = ['              = ', sPStr];
            bodyInfoStr{end+1} = hrule;

            bodyInfoStr{end+1} = 'Derived';
            bodyInfoStr{end+1} = [' Orbit Period = ', periodStr];
            bodyInfoStr{end+1} = [' Orbit RPe = ', rPeStr];
            bodyInfoStr{end+1} = [' Orbit RAp = ', rApStr];
            bodyInfoStr{end+1} = [' SoI Radius = ', rSOIStr];
            bodyInfoStr{end+1} = [' Sync. SMA = ', num2str(syncSMA), ' km',syncNotPoss];
            bodyInfoStr{end+1} = hrule;
            
            bodyInfoStr{end+1} = 'Miscellaneous';
            bodyInfoStr{end+1} = [' Propagation Type = ', bodyInfo.propTypeEnum.name];
        end

        function plotAtmoCurve(~, orbitDispAxes, bodyInfo, ~)
            atmoHgt = bodyInfo.atmohgt;

            if(atmoHgt > 0)
                hArr = linspace(0,atmoHgt,100);
                density = zeros(size(hArr));
                for(i=1:length(hArr)) %#ok<*NO4LP> 
                    density(i) = getAtmoDensityAtAltitude(bodyInfo, hArr(i), deg2rad(45), 0, 0);
                end

                plot(orbitDispAxes,hArr,density,'r-','LineWidth',2);
                v = ylim(orbitDispAxes);
                grid on;
                xlim(orbitDispAxes, [min(hArr), max(hArr)]);
                ylim(orbitDispAxes, [0, v(2)]);
                xlabel('Altitude [km]');
                ylabel('Density [kg/m^3]');
            end
        end

        function plotBody(~, hFig, orbitDispAxes, bodyInfo)
            ma_initOrbPlot(hFig, orbitDispAxes, bodyInfo);
        end

        function setupCelBodyListbox(app, handles, celBodyData)
            bodyInfo = celBodyData.getTopLevelBody();
            node = uitreenode(app.CatalogBodiesTree, 'Text', bodyInfo.name, 'NodeData',bodyInfo);
            
            app.createNodesForChildren(node, bodyInfo, celBodyData);

            app.CatalogBodiesTree.SelectedNodes = node;
            expand(app.CatalogBodiesTree,'all');
            
%             bodiesStr = ma_getSortedBodyNames(celBodyData);
% 
%             set(handles.catalogListbox, 'String', bodiesStr);
%             if(length(bodiesStr) >= 1)
%                 set(handles.catalogListbox, 'value', 1);
%             end
        end
        
        function createNodesForChildren(app, parentNode, bodyInfo, celBodyData)
            [childrenBodyInfo, ~] = bodyInfo.getChildrenBodyInfo(celBodyData);
            
            for(i=1:length(childrenBodyInfo))
                childBodyInfo = childrenBodyInfo(i);
                node = uitreenode(parentNode, 'Text', childBodyInfo.name, 'NodeData',childBodyInfo);
                
                app.createNodesForChildren(node, childBodyInfo, celBodyData);
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ma_CelBodyCatalogGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to ma_CelBodyCatalogGUI (see VARARGIN)

            % Choose default command line output for ma_CelBodyCatalogGUI
            handles.output = hObject;

            celBodyData = varargin{1};
            setappdata(hObject,'celBodyData',celBodyData);

            pos = get(handles.dispAxes,'Position');
            set(handles.displayAtmoRadio,'UserData',pos);

            setupCelBodyListbox(app, handles, celBodyData);
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            catalogListbox_Callback(app, evt);

            % Update handles structure
            guidata(hObject, handles);
        end

        % Selection changed function: CatalogBodiesTree
        function catalogListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = app.CatalogBodiesTree;
            
            % hObject    handle to catalogListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns catalogListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from catalogListbox
%             contents = cellstr(get(hObject,'String'));
%             selected = contents{get(hObject,'Value')};
            selectedNode = hObject.SelectedNodes;
            bodyInfo = selectedNode.NodeData;

            celBodyData = getappdata(handles.ma_CelBodyCatalogGUI,'celBodyData');
%             bodyInfo = celBodyData.(strtrim(lower(selected)));
            parentBodyInfo = bodyInfo.getParBodyInfo(celBodyData);
            bodyInfoStr = generateBodyInfoStr(app, bodyInfo, celBodyData);
            set(handles.infoLabel,'String',bodyInfoStr);

            dispBodyValue = get(handles.displayBodyRadio, 'Value');
            dispOrbValue = get(handles.displayOrbitRadio, 'Value');
            dispAtmoValue = get(handles.displayAtmoRadio, 'Value');

            if(bodyInfo.atmohgt == 0)
                set(handles.displayAtmoRadio, 'Enable', 'off');
                if(dispAtmoValue==1)
                    set(handles.displayAtmoRadio, 'Value', 0);
                    set(handles.displayBodyRadio, 'Value', 1);

                    dispBodyValue = get(handles.displayBodyRadio, 'Value');
                    dispAtmoValue = get(handles.displayAtmoRadio, 'Value');
                end
            else
                set(handles.displayAtmoRadio, 'Enable', 'on');
            end

            cla(handles.dispAxes);
            xlabel('');
            ylabel('');
            axis(handles.dispAxes, 'normal');
            if(dispBodyValue==1)
                plotBody(app, handles.ma_CelBodyCatalogGUI,handles.dispAxes,bodyInfo);
                set(handles.dispAxes,'Position',get(handles.displayAtmoRadio,'UserData'));
                view(handles.dispAxes,3);
            elseif(dispOrbValue==1)
                if(~isempty(parentBodyInfo))
                    plotBodyOrbit(bodyInfo, 'r', getParentGM(bodyInfo, celBodyData));
                    plotBody(app, handles.ma_CelBodyCatalogGUI,handles.dispAxes,parentBodyInfo);
                else
                    plotBody(app, handles.ma_CelBodyCatalogGUI,handles.dispAxes,bodyInfo);
                end
                set(handles.dispAxes,'Position',get(handles.displayAtmoRadio,'UserData'));
                view(handles.dispAxes,3);
            elseif(dispAtmoValue==1)
                plotAtmoCurve(app, handles.dispAxes, bodyInfo, celBodyData);
                set(handles.dispAxes,'OuterPosition',get(handles.displayAtmoRadio,'UserData'));
                view(handles.dispAxes,2);
            end
        end

        % Selection changed function: dispAxesButtonGrp
        function dispAxesButtonGrp_SelectionChangeFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to the selected object in dispAxesButtonGrp
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            % handles    structure with handles and user data (see GUIDATA)
            catalogListbox_Callback(app, event);
        end

        % Window key press function: ma_CelBodyCatalogGUI
        function ma_CelBodyCatalogGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to ma_CelBodyCatalogGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            switch(eventdata.Key)
                case 'escape'
                    close(handles.ma_CelBodyCatalogGUI);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ma_CelBodyCatalogGUI = uifigure('Visible', 'off');
            app.ma_CelBodyCatalogGUI.Name = 'Celestial Body Catalog';
            app.ma_CelBodyCatalogGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.ma_CelBodyCatalogGUI.WindowKeyPressFcn = createCallbackFcn(app, @ma_CelBodyCatalogGUI_WindowKeyPressFcn, true);
            app.ma_CelBodyCatalogGUI.Tag = 'ma_CelBodyCatalogGUI';
            app.GridLayout = uigridlayout(app.ma_CelBodyCatalogGUI);
            app.text1 = uilabel(app.GridLayout);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Celestial Body Catalog';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CatalogBodiesTree = uitree(app.GridLayout2);
            app.CatalogBodiesTree.SelectionChangedFcn = createCallbackFcn(app, @catalogListbox_Callback, true);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Panel = uipanel(app.GridLayout3);
            app.GridLayout5 = uigridlayout(app.Panel);
            app.dispAxes = uiaxes(app.GridLayout5);
            app.dispAxes.Tag = 'dispAxes';
            app.uipanel1 = uipanel(app.GridLayout3);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout4 = uigridlayout(app.uipanel1);
            app.infoLabel = uilabel(app.GridLayout4);
            app.infoLabel.Tag = 'infoLabel';
            app.infoLabel.Text = {'Name: Kerbin'; 'ID Num: 1'; 'Parent: Sun'; '-------------------------------'; 'Orbit'; ' Epoch = Year 1, Day 1 00:00:00.000'; ' SMA = 13599840.256 km'; ' Ecc = 0.00'; ' Inc = 0.00 deg'; ' RAAN = 0.00 deg'; ' Arg = 0.00 deg'; ' Mean = 179.90875 deg'; '---------------------'; 'Physical Properties'; ' GM = 3531.6 km^3/s^2'; ' Radius = 600 km'; ' Atmo Height = 69.0775 km'; ' Sidereal Day = 21600 sec'; '---------------------'; 'Derived'; ' Orbit Period = .... days'; ' Orbit RPe = 13599840.256 km'; ' Orbit RAp = 13599840.256 km'; ' SoI Radius = .....'};
            app.dispAxesButtonGrp = uibuttongroup(app.GridLayout3);
            app.dispAxesButtonGrp.SelectionChangedFcn = createCallbackFcn(app, @dispAxesButtonGrp_SelectionChangeFcn, true);
            app.dispAxesButtonGrp.Tag = 'dispAxesButtonGrp';
            app.displayBodyRadio = uiradiobutton(app.dispAxesButtonGrp);
            app.displayBodyRadio.Tag = 'displayBodyRadio';
            app.displayBodyRadio.Text = 'Display Body';
            app.displayBodyRadio.Value = true;
            app.displayOrbitRadio = uiradiobutton(app.dispAxesButtonGrp);
            app.displayOrbitRadio.Tag = 'displayOrbitRadio';
            app.displayOrbitRadio.Text = 'Display Orbit';
            app.displayAtmoRadio = uiradiobutton(app.dispAxesButtonGrp);
            app.displayAtmoRadio.Tag = 'displayAtmoRadio';
            app.displayAtmoRadio.Tooltip = 'Displays the pressure curve of the atmosphere, if one is present.';
            app.displayAtmoRadio.Text = 'Display Atmo.';
            app.uipanel1 = uipanel(app.GridLayout3);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout4 = uigridlayout(app.uipanel1);
            app.infoLabel = uilabel(app.GridLayout4);
            app.infoLabel.Tag = 'infoLabel';
            app.infoLabel.Text = {'Name: Kerbin'; 'ID Num: 1'; 'Parent: Sun'; '-------------------------------'; 'Orbit'; ' Epoch = Year 1, Day 1 00:00:00.000'; ' SMA = 13599840.256 km'; ' Ecc = 0.00'; ' Inc = 0.00 deg'; ' RAAN = 0.00 deg'; ' Arg = 0.00 deg'; ' Mean = 179.90875 deg'; '---------------------'; 'Physical Properties'; ' GM = 3531.6 km^3/s^2'; ' Radius = 600 km'; ' Atmo Height = 69.0775 km'; ' Sidereal Day = 21600 sec'; '---------------------'; 'Derived'; ' Orbit Period = .... days'; ' Orbit RPe = 13599840.256 km'; ' Orbit RAp = 13599840.256 km'; ' SoI Radius = .....'};
            app.uipanel1 = uipanel(app.GridLayout3);
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout4 = uigridlayout(app.uipanel1);
            app.infoLabel = uilabel(app.GridLayout4);
            app.infoLabel.Tag = 'infoLabel';
            app.infoLabel.Text = {'Name: Kerbin'; 'ID Num: 1'; 'Parent: Sun'; '-------------------------------'; 'Orbit'; ' Epoch = Year 1, Day 1 00:00:00.000'; ' SMA = 13599840.256 km'; ' Ecc = 0.00'; ' Inc = 0.00 deg'; ' RAAN = 0.00 deg'; ' Arg = 0.00 deg'; ' Mean = 179.90875 deg'; '---------------------'; 'Physical Properties'; ' GM = 3531.6 km^3/s^2'; ' Radius = 600 km'; ' Atmo Height = 69.0775 km'; ' Sidereal Day = 21600 sec'; '---------------------'; 'Derived'; ' Orbit Period = .... days'; ' Orbit RPe = 13599840.256 km'; ' Orbit RAp = 13599840.256 km'; ' SoI Radius = .....'};
            % Show the figure after all components are created
            app.ma_CelBodyCatalogGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ma_CelBodyCatalogGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ma_CelBodyCatalogGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)ma_CelBodyCatalogGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ma_CelBodyCatalogGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ma_CelBodyCatalogGUI)
        end
    end
end