classdef ma_ObserveOptimGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ma_ObserveOptimGUI    matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        GridLayout2           matlab.ui.container.GridLayout
        GridLayout4           matlab.ui.container.GridLayout
        uipanel3              matlab.ui.container.Panel
        GridLayout7           matlab.ui.container.GridLayout
        finalStateOptimLabel  matlab.ui.control.Label
        uipanel4              matlab.ui.container.Panel
        GridLayout6           matlab.ui.container.GridLayout
        optimStatusLabel      matlab.ui.control.Label
        GridLayout5           matlab.ui.container.GridLayout
        cancelButton          matlab.ui.control.StateButton
        pauseButton           matlab.ui.control.Button
        dispAxesPanel         matlab.ui.container.Panel
        text1                 matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ma_ObserveOptimGUI_OpeningFcn(app, out)
            arguments
                app(1,1) ma_ObserveOptimGUI_App
                out(1,1) AppDesignerGUIOutput
            end
            
            % Ensure that the app appears on screen when run
            centerUIFigure(app.ma_ObserveOptimGUI);
            applySelectedThemeToApp(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to ma_ObserveOptimGUI (see VARARGIN)
            
            % Choose default command line output for ma_ObserveOptimGUI
            handles.output = hObject;
                       
            % Update handles structure
            guidata(hObject, handles);

            out.output{1} = handles;
            out.output{2} = app;
            
            set(hObject, 'Visible','on');
            set(handles.cancelButton,'UserData',false);
            drawnow;
            
%             set(handles.dispAxes,'XTickLabel',[]);
%             set(handles.dispAxes,'YTickLabel',[]);
%             set(handles.dispAxes,'ZTickLabel',[]);
%             set(handles.dispAxes,'Visible','off');
            drawnow;
            
            datacursormode(handles.ma_ObserveOptimGUI,'on');
        end

        % Value changed function: cancelButton
        function cancelButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                set(handles.cancelButton,'UserData',true);
        end

        % Button pushed function: pauseButton
        function pauseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pauseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                h = msgbox('Optimization has been paused.  Push OK to continue.','Paused','help','modal');
                uiwait(h);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ma_ObserveOptimGUI = uifigure('Visible', 'off');
            colormap(app.ma_ObserveOptimGUI, 'parula');
            app.ma_ObserveOptimGUI.Name = 'Mission Optimizer';
            app.ma_ObserveOptimGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.ma_ObserveOptimGUI.Tag = 'ma_ObserveOptimGUI';
            app.GridLayout = uigridlayout(app.ma_ObserveOptimGUI);
            app.text1 = uilabel(app.GridLayout);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Mission Optimizer';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.dispAxesPanel = uipanel(app.GridLayout2);
            app.dispAxesPanel.Tag = 'dispAxesPanel';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.pauseButton = uibutton(app.GridLayout5, 'push');
            app.pauseButton.ButtonPushedFcn = createCallbackFcn(app, @pauseButton_Callback, true);
            app.pauseButton.Tag = 'pauseButton';
            app.pauseButton.Icon = 'pause.png';
            app.pauseButton.Tooltip = 'Pauses mission optimization.  Be patient, this button will only take effect after the current iteration completes.';
            app.pauseButton.Text = 'Pause';
            app.cancelButton = uibutton(app.GridLayout5, 'state');
            app.cancelButton.ValueChangedFcn = createCallbackFcn(app, @cancelButton_Callback, true);
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Tooltip = 'Stops mission optimization and returns the current result.  Be patient, this button will only take effect after the current iteration completes.';
            app.cancelButton.Icon = 'cancel.png';
            app.cancelButton.Text = 'Stop';
            app.uipanel4 = uipanel(app.GridLayout4);
            app.uipanel4.Title = 'Optimizer Status';
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout6 = uigridlayout(app.uipanel4);
            app.optimStatusLabel = uilabel(app.GridLayout6);
            app.optimStatusLabel.Tag = 'optimStatusLabel';
            app.optimStatusLabel.Text = {'State                = '; ' '; 'Iteration            = '; 'Function Evals       = '; 'Objective Value      = '; 'Constraint Violation = '; 'Optimality           = '; 'Step Size            = '; ' '; 'Elapsed Time         ='};
            app.uipanel3 = uipanel(app.GridLayout4);
            app.uipanel3.Title = 'Final Spacecraft State';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout7 = uigridlayout(app.uipanel3);
            app.finalStateOptimLabel = uilabel(app.GridLayout7);
            app.finalStateOptimLabel.Tag = 'finalStateOptimLabel';
            app.finalStateOptimLabel.Text = {'Universal Time (UT)     ='; '  '; 'Semi-major Axis         = '; 'Eccentricity            ='; 'Inclination             ='; 'Right Asc. of Asc. Node = '; 'Argument of Periapse    ='; 'True Anomaly            ='; '   '; 'Dry Mass                ='; 'Fuel/Ox Mass            ='; 'Monopropellant Mass     ='; 'Xenon Mass              ='; 'Total Mass              ='};
            % Show the figure after all components are created
            app.ma_ObserveOptimGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ma_ObserveOptimGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ma_ObserveOptimGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)ma_ObserveOptimGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ma_ObserveOptimGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ma_ObserveOptimGUI)
        end
    end
end