classdef ma_OptimResultsScorecardGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ma_OptimResultsScorecardGUI   matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout2                   matlab.ui.container.GridLayout
        uipanel3                      matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        text21                        matlab.ui.control.Label
        acceptLowestConstrViolIterationButton  matlab.ui.control.Button
        lowConstrMaxConstrViolLabel   matlab.ui.control.Label
        text19                        matlab.ui.control.Label
        lowConstrObjFuncValueLabel    matlab.ui.control.Label
        text17                        matlab.ui.control.Label
        lowConstrIterNumLabel         matlab.ui.control.Label
        uipanel2                      matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        lowFuncMaxConstrViolLabel     matlab.ui.control.Label
        text13                        matlab.ui.control.Label
        lowFuncObjFuncValueLabel      matlab.ui.control.Label
        text11                        matlab.ui.control.Label
        lowFuncIterNumLabel           matlab.ui.control.Label
        text15                        matlab.ui.control.Label
        acceptLowestFuncValueIterButton  matlab.ui.control.Button
        uipanel1                      matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        acceptFinalIterResultsButton  matlab.ui.control.Button
        finalMaxConstrViolLabel       matlab.ui.control.Label
        text7                         matlab.ui.control.Label
        finalObjFuncValueLabel        matlab.ui.control.Label
        text4                         matlab.ui.control.Label
        finalIterNumLabel             matlab.ui.control.Label
        text9                         matlab.ui.control.Label
        text2                         matlab.ui.control.Label
        discardOptimResultsButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        out AppDesignerGUIOutput
    end
    
    methods (Access = private)
        function setLabelGreen(app, hLabel)
                set(hLabel,'BackgroundColor',[34,139,34]/255);
                set(hLabel,'ForegroundColor',[1,1,1]);
                set(hLabel,'FontWeight','bold');
        end
        
        function setupUI(app, recorder, handles)
                [finalIterNum, ~, finalFVal, finalMaxCVal] = recorder.getLastIter();
                [lowFuncIterNum, ~, lowFuncFVal, lowFuncMaxCVal] = recorder.getIterWithLowestFVal();
                [lowConstrIterNum, ~, lowConstrFVal, lowConstrMaxCVal] = recorder.getIterWithLowestCVal();
            
                set(handles.finalIterNumLabel,'String',num2str(finalIterNum));
                set(handles.finalObjFuncValueLabel,'String',fullAccNum2Str(finalFVal));
                set(handles.finalMaxConstrViolLabel,'String',fullAccNum2Str(finalMaxCVal));
            
                set(handles.lowFuncIterNumLabel,'String',num2str(lowFuncIterNum));
                set(handles.lowFuncObjFuncValueLabel,'String',fullAccNum2Str(lowFuncFVal));
                set(handles.lowFuncMaxConstrViolLabel,'String',fullAccNum2Str(lowFuncMaxCVal));
            
                set(handles.lowConstrIterNumLabel,'String',num2str(lowConstrIterNum));
                set(handles.lowConstrObjFuncValueLabel,'String',fullAccNum2Str(lowConstrFVal));
                set(handles.lowConstrMaxConstrViolLabel,'String',fullAccNum2Str(lowConstrMaxCVal));
            
                [minFVal,~] = min([finalFVal, lowFuncFVal, lowConstrFVal]);
                if(finalFVal == minFVal)
                    setLabelGreen(app, handles.finalObjFuncValueLabel)
                end
                if(lowFuncFVal == minFVal)
                    setLabelGreen(app, handles.lowFuncObjFuncValueLabel)
                end
                if(lowConstrFVal == minFVal)
                    setLabelGreen(app, handles.lowConstrObjFuncValueLabel)
                end
            
                [minMaxCVal,~] = min([finalMaxCVal, lowFuncMaxCVal, lowConstrMaxCVal]);
                if(finalMaxCVal == minMaxCVal)
                    setLabelGreen(app, handles.finalMaxConstrViolLabel)
                end
                if(lowFuncMaxCVal == minMaxCVal)
                    setLabelGreen(app, handles.lowFuncMaxConstrViolLabel)
                end
                if(lowConstrMaxCVal == minMaxCVal)
                    setLabelGreen(app, handles.lowConstrMaxConstrViolLabel)
                end
        end
        
        
        function outputFunc(app)
            recorder = getappdata(app.ma_OptimResultsScorecardGUI,'recorder');
            selection = getappdata(app.ma_OptimResultsScorecardGUI,'selection');
            
            switch selection
                case 'final'
                    [x, xVals] = recorder.getLastIter();
                case 'lowFunc'
                    [x, xVals] = recorder.getIterWithLowestFVal();
                case 'lowConstr'
                    [x, xVals] = recorder.getIterWithLowestCVal();
                case 'discard'
                    x = [];
                    xVals = [];
            end
            
            app.out.output{1} = x;
            app.out.output{2} = xVals;
            close(app.ma_OptimResultsScorecardGUI);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ma_OptimResultsScorecardGUI_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            centerUIFigure(app.ma_OptimResultsScorecardGUI);
            applySelectedThemeToApp(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to ma_OptimResultsScorecardGUI (see VARARGIN)
            
            % Choose default command line output for ma_OptimResultsScorecardGUI
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            recorder = varargin{1};
            setappdata(hObject,'recorder',recorder);

            app.out = varargin{2};
            
            setupUI(app, recorder, handles);
            
            % UIWAIT makes ma_OptimResultsScorecardGUI wait for user response (see UIRESUME)
            uiwait(handles.ma_OptimResultsScorecardGUI);
        end

        % Button pushed function: acceptFinalIterResultsButton
        function acceptFinalIterResultsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to acceptFinalIterResultsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                setappdata(handles.ma_OptimResultsScorecardGUI,'selection','final');
%                 uiresume(handles.ma_OptimResultsScorecardGUI);
                app.outputFunc();
        end

        % Button pushed function: acceptLowestConstrViolIterationButton
        function acceptLowestConstrViolIterationButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to acceptLowestConstrViolIterationButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                setappdata(handles.ma_OptimResultsScorecardGUI,'selection','lowConstr');
%                 uiresume(handles.ma_OptimResultsScorecardGUI);
                app.outputFunc();
        end

        % Button pushed function: acceptLowestFuncValueIterButton
        function acceptLowestFuncValueIterButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to acceptLowestFuncValueIterButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                setappdata(handles.ma_OptimResultsScorecardGUI,'selection','lowFunc');
%                 uiresume(handles.ma_OptimResultsScorecardGUI);
                app.outputFunc();
        end

        % Button pushed function: discardOptimResultsButton
        function discardOptimResultsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to discardOptimResultsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                setappdata(handles.ma_OptimResultsScorecardGUI,'selection','discard');
%                 uiresume(handles.ma_OptimResultsScorecardGUI);
                app.outputFunc();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ma_OptimResultsScorecardGUI = uifigure('Visible', 'off');
            app.ma_OptimResultsScorecardGUI.Name = 'Optimization Results Scorecard';
            app.ma_OptimResultsScorecardGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.ma_OptimResultsScorecardGUI.Tag = 'ma_OptimResultsScorecardGUI';
            app.GridLayout = uigridlayout(app.ma_OptimResultsScorecardGUI);
            app.discardOptimResultsButton = uibutton(app.GridLayout, 'push');
            app.discardOptimResultsButton.ButtonPushedFcn = createCallbackFcn(app, @discardOptimResultsButton_Callback, true);
            app.discardOptimResultsButton.Tag = 'discardOptimResultsButton';
            app.discardOptimResultsButton.Icon = 'cancel.png';
            app.discardOptimResultsButton.Tooltip = 'Uses none of the optimization results and reverts the mission plan to its pre-optimization state.';
            app.discardOptimResultsButton.Text = 'Discard All Optimization Results';
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Optimization Results Scorecard';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.uipanel1 = uipanel(app.GridLayout2);
            app.uipanel1.Title = 'Final Iteration Results';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.text9 = uilabel(app.GridLayout3);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Iteration Number';
            app.finalIterNumLabel = uilabel(app.GridLayout3);
            app.finalIterNumLabel.Tag = 'finalIterNumLabel';
            app.finalIterNumLabel.Text = '1234.56';
            app.text4 = uilabel(app.GridLayout3);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Objective Function Value';
            app.finalObjFuncValueLabel = uilabel(app.GridLayout3);
            app.finalObjFuncValueLabel.Tag = 'finalObjFuncValueLabel';
            app.finalObjFuncValueLabel.Text = '1234.56';
            app.text7 = uilabel(app.GridLayout3);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Maximum Constraint Violation';
            app.finalMaxConstrViolLabel = uilabel(app.GridLayout3);
            app.finalMaxConstrViolLabel.Tag = 'finalMaxConstrViolLabel';
            app.finalMaxConstrViolLabel.Text = '1234.56';
            app.acceptFinalIterResultsButton = uibutton(app.GridLayout3, 'push');
            app.acceptFinalIterResultsButton.ButtonPushedFcn = createCallbackFcn(app, @acceptFinalIterResultsButton_Callback, true);
            app.acceptFinalIterResultsButton.Tag = 'acceptFinalIterResultsButton';
            app.acceptFinalIterResultsButton.Tooltip = 'Sets the results of the optimization run to that of the optimizer''s last iteration.';
            app.acceptFinalIterResultsButton.Text = 'Use Final Iteration Results';
            app.uipanel2 = uipanel(app.GridLayout2);
            app.uipanel2.Title = 'Lowest Functional Value Iteration';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4 = uigridlayout(app.uipanel2);
            app.acceptLowestFuncValueIterButton = uibutton(app.GridLayout4, 'push');
            app.acceptLowestFuncValueIterButton.ButtonPushedFcn = createCallbackFcn(app, @acceptLowestFuncValueIterButton_Callback, true);
            app.acceptLowestFuncValueIterButton.Tag = 'acceptLowestFuncValueIterButton';
            app.acceptLowestFuncValueIterButton.Tooltip = 'Sets the results of the optimization run to that iteration with the lowest functional value, regardless of the constraint value.  Uses smallest constraint violation iteration in event of a tie.';
            app.acceptLowestFuncValueIterButton.Text = 'Use Lowest Func. Value Iteration';
            app.text15 = uilabel(app.GridLayout4);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Iteration Number';
            app.lowFuncIterNumLabel = uilabel(app.GridLayout4);
            app.lowFuncIterNumLabel.Tag = 'lowFuncIterNumLabel';
            app.lowFuncIterNumLabel.Text = '1234.56';
            app.text11 = uilabel(app.GridLayout4);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Objective Function Value';
            app.lowFuncObjFuncValueLabel = uilabel(app.GridLayout4);
            app.lowFuncObjFuncValueLabel.Tag = 'lowFuncObjFuncValueLabel';
            app.lowFuncObjFuncValueLabel.Text = '1234.56';
            app.text13 = uilabel(app.GridLayout4);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Maximum Constraint Violation';
            app.lowFuncMaxConstrViolLabel = uilabel(app.GridLayout4);
            app.lowFuncMaxConstrViolLabel.Tag = 'lowFuncMaxConstrViolLabel';
            app.lowFuncMaxConstrViolLabel.Text = '1234.56';
            app.uipanel3 = uipanel(app.GridLayout2);
            app.uipanel3.Title = 'Lowest Constraint Violation Iteration';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout5 = uigridlayout(app.uipanel3);
            app.lowConstrIterNumLabel = uilabel(app.GridLayout5);
            app.lowConstrIterNumLabel.Tag = 'lowConstrIterNumLabel';
            app.lowConstrIterNumLabel.Text = '1234.56';
            app.text17 = uilabel(app.GridLayout5);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Objective Function Value';
            app.lowConstrObjFuncValueLabel = uilabel(app.GridLayout5);
            app.lowConstrObjFuncValueLabel.Tag = 'lowConstrObjFuncValueLabel';
            app.lowConstrObjFuncValueLabel.Text = '1234.56';
            app.text19 = uilabel(app.GridLayout5);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Maximum Constraint Violation';
            app.lowConstrMaxConstrViolLabel = uilabel(app.GridLayout5);
            app.lowConstrMaxConstrViolLabel.Tag = 'lowConstrMaxConstrViolLabel';
            app.lowConstrMaxConstrViolLabel.Text = '1234.56';
            app.acceptLowestConstrViolIterationButton = uibutton(app.GridLayout5, 'push');
            app.acceptLowestConstrViolIterationButton.ButtonPushedFcn = createCallbackFcn(app, @acceptLowestConstrViolIterationButton_Callback, true);
            app.acceptLowestConstrViolIterationButton.Tag = 'acceptLowestConstrViolIterationButton';
            app.acceptLowestConstrViolIterationButton.Tooltip = 'Sets the results of the optimization run to that iteration with the smallest maximum violated constraint value, regardless of the functional value.  Uses smallest functional value iteration in event of a tie.';
            app.acceptLowestConstrViolIterationButton.Text = 'Use Lowest Constr. Viol. Iteration';
            app.text21 = uilabel(app.GridLayout5);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Iteration Number';
            % Show the figure after all components are created
            app.ma_OptimResultsScorecardGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ma_OptimResultsScorecardGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ma_OptimResultsScorecardGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)ma_OptimResultsScorecardGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ma_OptimResultsScorecardGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ma_OptimResultsScorecardGUI)
        end
    end
end