classdef AppThemeEditorGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AppThemeEditorUIFigure   matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout14             matlab.ui.container.GridLayout
        Image                    matlab.ui.control.Image
        titleText                matlab.ui.control.Label
        Panel_2                  matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        Panel_3                  matlab.ui.container.Panel
        GridLayout5              matlab.ui.container.GridLayout
        GridLayout15             matlab.ui.container.GridLayout
        GridLayout18             matlab.ui.container.GridLayout
        ActiveThemeCheckBox      matlab.ui.control.CheckBox
        GridLayout17             matlab.ui.container.GridLayout
        ThemeDescEdit            matlab.ui.control.EditField
        ThemeDescriptionLabel    matlab.ui.control.Label
        GridLayout16             matlab.ui.container.GridLayout
        ThemeNameEdit            matlab.ui.control.EditField
        ThemeNameLabel           matlab.ui.control.Label
        TabGroup                 matlab.ui.container.TabGroup
        AppWideTab               matlab.ui.container.Tab
        GridLayout9              matlab.ui.container.GridLayout
        PanelBorderWidthSpinner  matlab.ui.control.Spinner
        PanelBorderWidthLabel    matlab.ui.control.Label
        PanelBorderColor         wt.ColorSelector
        PanelBorderColorLabel    matlab.ui.control.Label
        AppFontColor             wt.ColorSelector
        FontColorLabel           matlab.ui.control.Label
        AppBgColor               wt.ColorSelector
        BackgroundColorLabel     matlab.ui.control.Label
        ButtonsTab               matlab.ui.container.Tab
        GridLayout10             matlab.ui.container.GridLayout
        ButtonFontColor          wt.ColorSelector
        FontColorLabel_2         matlab.ui.control.Label
        ButtonBgColor            wt.ColorSelector
        BackgroundColorLabel_2   matlab.ui.control.Label
        TextEditTab              matlab.ui.container.Tab
        GridLayout11             matlab.ui.container.GridLayout
        EditTextFontColor        wt.ColorSelector
        EditTextBgColor          wt.ColorSelector
        FontColorLabel_3         matlab.ui.control.Label
        BackgroundColorLabel_3   matlab.ui.control.Label
        DropdownTab              matlab.ui.container.Tab
        GridLayout11_2           matlab.ui.container.GridLayout
        DropdownFontColor        wt.ColorSelector
        DropdownBgColor          wt.ColorSelector
        FontColorLabel_5         matlab.ui.control.Label
        BackgroundColorLabel_6   matlab.ui.control.Label
        ListboxTab               matlab.ui.container.Tab
        GridLayout12             matlab.ui.container.GridLayout
        ListboxFontColor         wt.ColorSelector
        FontColorLabel_4         matlab.ui.control.Label
        ListboxBgColor           wt.ColorSelector
        BackgroundColorLabel_4   matlab.ui.control.Label
        SliderTab                matlab.ui.container.Tab
        GridLayout19             matlab.ui.container.GridLayout
        FontColorLabel_7         matlab.ui.control.Label
        SliderFontColor          wt.ColorSelector
        Table                    matlab.ui.container.Tab
        GridLayout12_2           matlab.ui.container.GridLayout
        TableFontColor           wt.ColorSelector
        FontColorLabel_8         matlab.ui.control.Label
        TableBg2Color            wt.ColorSelector
        BackgroundColor2Label    matlab.ui.control.Label
        TableBg1Color            wt.ColorSelector
        BackgroundColor1Label    matlab.ui.control.Label
        AxesTab                  matlab.ui.container.Tab
        GridLayout13             matlab.ui.container.GridLayout
        AxesGridColor            wt.ColorSelector
        GridColorLabel           matlab.ui.control.Label
        AxesBgColor              wt.ColorSelector
        BackgroundColorLabel_5   matlab.ui.control.Label
        ExampleGUIPanel          matlab.ui.container.Panel
        ExampleGridLayout1       matlab.ui.container.GridLayout
        ExampleGridLayout3       matlab.ui.container.GridLayout
        ExampleUITable           matlab.ui.control.Table
        ExampleUIAxes            matlab.ui.control.UIAxes
        ExampleGridLayout2       matlab.ui.container.GridLayout
        ExampleSpinner           matlab.ui.control.Spinner
        ExampleSlider            matlab.ui.control.Slider
        ExampleTextArea          matlab.ui.control.TextArea
        ExampleListBox           matlab.ui.control.ListBox
        ExampleDropDown          matlab.ui.control.DropDown
        ExampleEditField         matlab.ui.control.EditField
        ExampleButton            matlab.ui.control.Button
        GridLayout3              matlab.ui.container.GridLayout
        GridLayout4              matlab.ui.container.GridLayout
        DeleteThemeButton        matlab.ui.control.Button
        AddThemeButton           matlab.ui.control.Button
        ThemesListbox            matlab.ui.control.ListBox
        SaveCloseButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        appThemer(1,1) AppThemer
    end
    
    methods (Access = private)
        function populateGUI(app)
            x = 0:0.01:2*pi;
            y1 = sin(x);
            y2 = cos(x);
            y3 = sin(x + pi/4);
            hold(app.ExampleUIAxes,'on');
            plot(app.ExampleUIAxes, x,y1, '--');
            plot(app.ExampleUIAxes, x,y2, '--');
            plot(app.ExampleUIAxes, x,y3, '--');
            grid(app.ExampleUIAxes, "off"); grid(app.ExampleUIAxes, "minor");
            app.ExampleUIAxes.XLim = [min(x), max(x)];
            app.ExampleUIAxes.Title.String = "Example Plot";

            app.ExampleUITable.Data = [1,2,3; 3,5,6; 7,8,9];

            [listboxStr, themes, selectedTheme] = app.appThemer.getListrboxStr();
            app.ThemesListbox.Items = listboxStr;
            app.ThemesListbox.ItemsData = themes;
            app.ThemesListbox.Value = selectedTheme;

            app.styleThemeListbox();

            app.populateGUIForSelTheme(selectedTheme)
        end

        function styleThemeListbox(app)
            [~, themes, selectedTheme] = app.appThemer.getListrboxStr();

            removeStyle(app.ThemesListbox);
            addStyle(app.ThemesListbox, uistyle("Icon","success", "IconAlignment","leftmargin"), 'item', find(themes==selectedTheme,1,"first"));
        end
        
        function populateGUIForSelTheme(app, selTheme)
            arguments
                app(1,1) AppThemeEditorGUI_App
                selTheme(1,1) AppColorTheme
            end

            app.ThemeNameEdit.Value = selTheme.name;
            app.ThemeDescEdit.Value = selTheme.desc;
            app.ActiveThemeCheckBox.Value = app.appThemer.selTheme == selTheme;

            app.AppBgColor.Value = selTheme.bgColor;
            app.AppFontColor.Value = selTheme.fontColor;
            app.PanelBorderColor.Value = selTheme.borderColor;
            app.PanelBorderWidthSpinner.Value = selTheme.borderWidth;

            app.ButtonBgColor.Value = selTheme.buttonBgColor;
            app.ButtonFontColor.Value = selTheme.buttonFontColor;

            app.EditTextBgColor.Value = selTheme.editTextBgColor;
            app.EditTextFontColor.Value = selTheme.editTextFontColor;

            app.DropdownBgColor.Value = selTheme.dropDownBgColor;
            app.DropdownFontColor.Value = selTheme.dropDownFontColor;

            app.ListboxBgColor.Value = selTheme.listboxBgColor;
            app.ListboxFontColor.Value = selTheme.listboxFontColor;

            app.SliderFontColor.Value = selTheme.sliderFontColor;

            app.TableBg1Color.Value = selTheme.tableBgColor1;
            app.TableBg2Color.Value = selTheme.tableBgColor2;
            app.TableFontColor.Value = selTheme.tableFontColor;

            app.AxesBgColor.Value = selTheme.axesBgColor;
            app.AxesGridColor.Value = selTheme.axesGridColor;

            app.themeExampleGUI(app.ThemesListbox.Value);
        end
        
        function themeExampleGUI(app, theme)
            arguments
                app(1,1) AppThemeEditorGUI_App
                theme(1,1) AppColorTheme
            end

            app.appThemer.themeWidget(app.ExampleGUIPanel, theme);
            app.appThemer.themeWidget(app.ExampleGridLayout1, theme);
            app.appThemer.themeWidget(app.ExampleGridLayout2, theme);
            app.appThemer.themeWidget(app.ExampleGridLayout3, theme);
            app.appThemer.themeWidget(app.ExampleButton, theme);
            app.appThemer.themeWidget(app.ExampleEditField, theme);
            app.appThemer.themeWidget(app.ExampleDropDown, theme);
            app.appThemer.themeWidget(app.ExampleSlider, theme);
            app.appThemer.themeWidget(app.ExampleSpinner, theme);
            app.appThemer.themeWidget(app.ExampleTextArea, theme);
            app.appThemer.themeWidget(app.ExampleListBox, theme);
            app.appThemer.themeWidget(app.ExampleUIAxes, theme);
            app.appThemer.themeWidget(app.ExampleUITable, theme);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, appThemer)
           arguments
                app(1,1) AppThemeEditorGUI_App
                appThemer(1,1) AppThemer
           end

            % figPropStrs=properties(app);
            % for(i=1:length(figPropStrs)) %#ok<*NO4LP> 
            %     prop = app.(figPropStrs{i});
            %     disp(class(prop));
            % end

            app.appThemer = appThemer;

            centerUIFigure(app.AppThemeEditorUIFigure);
            applySelectedThemeToApp(app);

           app.populateGUI();
        end

        % Button pushed function: AddThemeButton
        function AddThemeButtonPushed(app, event)
            theme = AppThemer.createDefaultLightTheme();
            theme.name = 'New Theme';
            theme.desc = '';
            app.appThemer.addTheme(theme);

            app.populateGUI();
            app.ThemesListbox.Value = theme;
            app.populateGUIForSelTheme(theme);

            if(numel(app.ThemesListbox.ItemsData) <= 1)
                app.DeleteThemeButton.Enable = "off";
            else
                app.DeleteThemeButton.Enable = "on";
            end
        end

        % Button pushed function: DeleteThemeButton
        function DeleteThemeButtonPushed(app, event)
            if(numel(app.ThemesListbox.ItemsData) > 1)
                theme = app.ThemesListbox.Value;
                app.appThemer.deleteTheme(theme);

                app.populateGUI();
            end

            if(numel(app.ThemesListbox.ItemsData) <= 1)
                app.DeleteThemeButton.Enable = "off";
            else
                app.DeleteThemeButton.Enable = "on";
            end
        end

        % Button pushed function: SaveCloseButton
        function SaveCloseButtonPushed(app, event)
            app.appThemer.themeAllOpenApps();

            appThemeData = app.appThemer;
            save('appThemeData.mat','appThemeData');

            close(app.AppThemeEditorUIFigure);
        end

        % Value changed function: ThemesListbox
        function ThemesListboxValueChanged(app, event)
            theme = app.ThemesListbox.Value;
            app.populateGUIForSelTheme(theme);
        end

        % Callback function: AppBgColor
        function AppBgColorValueChanged(app, event)
            theme = app.ThemesListbox.Value;

            theme.bgColor = app.AppBgColor.Value;

            app.themeExampleGUI(theme);
        end

        % Callback function: AppFontColor
        function AppFontColorValueChanged(app, event)
            theme = app.ThemesListbox.Value;

            theme.fontColor = app.AppFontColor.Value;

            app.themeExampleGUI(theme);
        end

        % Callback function: PanelBorderColor
        function PanelBorderColorValueChanged(app, event)
            theme = app.ThemesListbox.Value;

            theme.borderColor = app.PanelBorderColor.Value;

            app.themeExampleGUI(theme);
        end

        % Callback function: ButtonBgColor
        function ButtonBgColorValueChanged(app, event)
            theme = app.ThemesListbox.Value;

            theme.buttonBgColor = app.ButtonBgColor.Value;

            app.themeExampleGUI(theme);
        end

        % Callback function: ButtonFontColor
        function ButtonFontColorValueChanged(app, event)
            theme = app.ThemesListbox.Value;

            theme.buttonFontColor = app.ButtonFontColor.Value;

            app.themeExampleGUI(theme);
        end

        % Callback function: EditTextBgColor
        function EditTextBgColorValueChanged(app, event)
            theme = app.ThemesListbox.Value;

            theme.editTextBgColor = app.EditTextBgColor.Value;

            app.themeExampleGUI(theme);
        end

        % Callback function: EditTextFontColor
        function EditTextFontColorValueChanged(app, event)
            theme = app.ThemesListbox.Value;

            theme.editTextFontColor = app.EditTextFontColor.Value;

            app.themeExampleGUI(theme);
        end

        % Callback function: DropdownBgColor
        function DropdownBgColorValueChanged(app, event)
            theme = app.ThemesListbox.Value;

            theme.dropDownBgColor = app.DropdownBgColor.Value;

            app.themeExampleGUI(theme);
        end

        % Callback function: DropdownFontColor
        function DropdownFontColorValueChanged(app, event)
            theme = app.ThemesListbox.Value;

            theme.dropDownFontColor = app.DropdownFontColor.Value;

            app.themeExampleGUI(theme);
        end

        % Callback function: ListboxBgColor
        function ListboxBgColorValueChanged(app, event)
            theme = app.ThemesListbox.Value;

            theme.listboxBgColor = app.ListboxBgColor.Value;

            app.themeExampleGUI(theme);
        end

        % Callback function: ListboxFontColor
        function ListboxFontColorValueChanged(app, event)
            theme = app.ThemesListbox.Value;

            theme.listboxFontColor = app.ListboxFontColor.Value;

            app.themeExampleGUI(theme);
        end

        % Callback function: SliderFontColor
        function SliderFontColorValueChanged(app, event)
            theme = app.ThemesListbox.Value;

            theme.sliderFontColor = app.SliderFontColor.Value;

            app.themeExampleGUI(theme);
        end

        % Callback function: AxesBgColor
        function AxesBgColorValueChanged(app, event)
            theme = app.ThemesListbox.Value;

            theme.axesBgColor = app.AxesBgColor.Value;

            app.themeExampleGUI(theme);
        end

        % Callback function: AxesGridColor
        function AxesGridColorValueChanged(app, event)
            theme = app.ThemesListbox.Value;

            theme.axesGridColor = app.AxesGridColor.Value;

            app.themeExampleGUI(theme);
        end

        % Value changing function: ThemeNameEdit
        function ThemeNameEditValueChanging(app, event)
            changingValue = event.Value;
            theme = app.ThemesListbox.Value;
            theme.name = changingValue;

            ind = app.ThemesListbox.ItemsData == theme;
            app.ThemesListbox.Items{ind} = changingValue;
            drawnow;
        end

        % Value changed function: ThemeDescEdit
        function ThemeDescEditValueChanged(app, event)
            value = app.ThemeDescEdit.Value;
            theme = app.ThemesListbox.Value;
            theme.desc = value;

            app.populateGUIForSelTheme(theme);
        end

        % Value changed function: ActiveThemeCheckBox
        function ActiveThemeCheckBoxValueChanged(app, event)
            theme = app.ThemesListbox.Value;
            value = app.ActiveThemeCheckBox.Value;
            
            if(value)
                app.appThemer.selTheme = theme;
            else
                bool = app.appThemer.themes ~= theme;
                if(any(bool))
                    app.appThemer.selTheme = app.appThemer.themes(bool(1));
                else
                    app.appThemer.selTheme = theme;
                end
            end

            app.styleThemeListbox();
            app.populateGUIForSelTheme(theme);
        end

        % Double-clicked callback: ThemesListbox
        function ThemesListboxDoubleClicked(app, event)
            theme = app.ThemesListbox.Value;
            app.appThemer.selTheme = theme;
            
            app.styleThemeListbox();
            app.populateGUIForSelTheme(theme);            
        end

        % Callback function: TableBg1Color
        function TableBg1ColorValueChanged(app, event)
            theme = app.ThemesListbox.Value;

            theme.tableBgColor1 = app.TableBg1Color.Value;

            app.themeExampleGUI(theme);
        end

        % Callback function: TableBg2Color
        function TableBg2ColorValueChanged(app, event)
            theme = app.ThemesListbox.Value;

            theme.tableBgColor2 = app.TableBg2Color.Value;

            app.themeExampleGUI(theme);
        end

        % Callback function: TableFontColor
        function TableFontColorValueChanged(app, event)
            theme = app.ThemesListbox.Value;

            theme.tableFontColor = app.TableFontColor.Value;

            app.themeExampleGUI(theme);
        end

        % Value changed function: PanelBorderWidthSpinner
        function PanelBorderWidthSpinnerValueChanged(app, event)
            theme = app.ThemesListbox.Value;

            theme.borderWidth = app.PanelBorderWidthSpinner.Value;

            app.themeExampleGUI(theme);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AppThemeEditorUIFigure = uifigure('Visible', 'off');
            app.AppThemeEditorUIFigure.Name = 'App Theme Editor';
            app.AppThemeEditorUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout = uigridlayout(app.AppThemeEditorUIFigure);
            app.SaveCloseButton = uibutton(app.GridLayout, 'push');
            app.SaveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCloseButtonPushed, true);
            app.SaveCloseButton.Icon = 'save-file.png';
            app.SaveCloseButton.Text = 'Save & Close';
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ThemesListbox = uilistbox(app.GridLayout3);
            app.ThemesListbox.ValueChangedFcn = createCallbackFcn(app, @ThemesListboxValueChanged, true);
            app.ThemesListbox.Tooltip = {'List of themes.  Double-click a theme to set it as the active one.'};
            app.ThemesListbox.DoubleClickedFcn = createCallbackFcn(app, @ThemesListboxDoubleClicked, true);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.AddThemeButton = uibutton(app.GridLayout4, 'push');
            app.AddThemeButton.ButtonPushedFcn = createCallbackFcn(app, @AddThemeButtonPushed, true);
            app.AddThemeButton.Icon = 'add.png';
            app.AddThemeButton.Text = 'Add Theme';
            app.DeleteThemeButton = uibutton(app.GridLayout4, 'push');
            app.DeleteThemeButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteThemeButtonPushed, true);
            app.DeleteThemeButton.Icon = 'cancel.png';
            app.DeleteThemeButton.Text = 'Delete Theme';
            app.Panel_3 = uipanel(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.Panel_3);
            app.ExampleGUIPanel = uipanel(app.GridLayout5);
            app.ExampleGUIPanel.Title = 'Example GUI';
            app.ExampleGridLayout1 = uigridlayout(app.ExampleGUIPanel);
            app.ExampleGridLayout2 = uigridlayout(app.ExampleGridLayout1);
            app.ExampleButton = uibutton(app.ExampleGridLayout2, 'push');
            app.ExampleButton.Icon = 'add.png';
            app.ExampleButton.Text = 'Example Button';
            app.ExampleEditField = uieditfield(app.ExampleGridLayout2, 'text');
            app.ExampleEditField.Value = 'Example Edit Text';
            app.ExampleDropDown = uidropdown(app.ExampleGridLayout2);
            app.ExampleListBox = uilistbox(app.ExampleGridLayout2);
            app.ExampleTextArea = uitextarea(app.ExampleGridLayout2);
            app.ExampleTextArea.Value = {'Example Text Area'};
            app.ExampleSlider = uislider(app.ExampleGridLayout2);
            app.ExampleSpinner = uispinner(app.ExampleGridLayout2);
            app.ExampleGridLayout3 = uigridlayout(app.ExampleGridLayout1);
            app.ExampleUIAxes = uiaxes(app.ExampleGridLayout3);
            title(app.ExampleUIAxes, 'Title')
            xlabel(app.ExampleUIAxes, 'X')
            ylabel(app.ExampleUIAxes, 'Y')
            zlabel(app.ExampleUIAxes, 'Z')
            app.ExampleUITable = uitable(app.ExampleGridLayout3);
            app.ExampleUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.ExampleUITable.RowName = {};
            app.GridLayout15 = uigridlayout(app.GridLayout5);
            app.TabGroup = uitabgroup(app.GridLayout15);
            app.AppWideTab = uitab(app.TabGroup);
            app.AppWideTab.Title = 'App-Wide';
            app.GridLayout9 = uigridlayout(app.AppWideTab);
            app.BackgroundColorLabel = uilabel(app.GridLayout9);
            app.BackgroundColorLabel.Text = 'Background Color';
            app.AppBgColor = wt.ColorSelector(app.GridLayout9);
            app.AppBgColor.ValueChangedFcn = createCallbackFcn(app, @AppBgColorValueChanged, true);
            app.FontColorLabel = uilabel(app.GridLayout9);
            app.FontColorLabel.Text = 'Font Color';
            app.AppFontColor = wt.ColorSelector(app.GridLayout9);
            app.AppFontColor.ValueChangedFcn = createCallbackFcn(app, @AppFontColorValueChanged, true);
            app.PanelBorderColorLabel = uilabel(app.GridLayout9);
            app.PanelBorderColorLabel.Text = 'Panel Border Color';
            app.PanelBorderColor = wt.ColorSelector(app.GridLayout9);
            app.PanelBorderColor.ValueChangedFcn = createCallbackFcn(app, @PanelBorderColorValueChanged, true);
            app.PanelBorderWidthLabel = uilabel(app.GridLayout9);
            app.PanelBorderWidthLabel.Text = 'Panel Border Width';
            app.PanelBorderWidthSpinner = uispinner(app.GridLayout9);
            app.PanelBorderWidthSpinner.Limits = [1 10];
            app.PanelBorderWidthSpinner.ValueDisplayFormat = '%0.2f';
            app.PanelBorderWidthSpinner.ValueChangedFcn = createCallbackFcn(app, @PanelBorderWidthSpinnerValueChanged, true);
            app.PanelBorderWidthSpinner.Tooltip = {'The width of panel borders, in pixels. '};
            app.PanelBorderWidthSpinner.Value = 1;
            app.ButtonsTab = uitab(app.TabGroup);
            app.ButtonsTab.Title = 'Buttons';
            app.GridLayout10 = uigridlayout(app.ButtonsTab);
            app.BackgroundColorLabel_2 = uilabel(app.GridLayout10);
            app.BackgroundColorLabel_2.Text = 'Background Color';
            app.ButtonBgColor = wt.ColorSelector(app.GridLayout10);
            app.ButtonBgColor.ValueChangedFcn = createCallbackFcn(app, @ButtonBgColorValueChanged, true);
            app.FontColorLabel_2 = uilabel(app.GridLayout10);
            app.FontColorLabel_2.Text = 'Font Color';
            app.ButtonFontColor = wt.ColorSelector(app.GridLayout10);
            app.ButtonFontColor.ValueChangedFcn = createCallbackFcn(app, @ButtonFontColorValueChanged, true);
            app.TextEditTab = uitab(app.TabGroup);
            app.TextEditTab.Title = 'Text Edit';
            app.GridLayout11 = uigridlayout(app.TextEditTab);
            app.BackgroundColorLabel_3 = uilabel(app.GridLayout11);
            app.BackgroundColorLabel_3.Text = 'Background Color';
            app.FontColorLabel_3 = uilabel(app.GridLayout11);
            app.FontColorLabel_3.Text = 'Font Color';
            app.EditTextBgColor = wt.ColorSelector(app.GridLayout11);
            app.EditTextBgColor.ValueChangedFcn = createCallbackFcn(app, @EditTextBgColorValueChanged, true);
            app.EditTextFontColor = wt.ColorSelector(app.GridLayout11);
            app.EditTextFontColor.ValueChangedFcn = createCallbackFcn(app, @EditTextFontColorValueChanged, true);
            app.DropdownTab = uitab(app.TabGroup);
            app.DropdownTab.Title = 'Dropdown';
            app.GridLayout11_2 = uigridlayout(app.DropdownTab);
            app.BackgroundColorLabel_6 = uilabel(app.GridLayout11_2);
            app.BackgroundColorLabel_6.Text = 'Background Color';
            app.FontColorLabel_5 = uilabel(app.GridLayout11_2);
            app.FontColorLabel_5.Text = 'Font Color';
            app.DropdownBgColor = wt.ColorSelector(app.GridLayout11_2);
            app.DropdownBgColor.ValueChangedFcn = createCallbackFcn(app, @DropdownBgColorValueChanged, true);
            app.DropdownFontColor = wt.ColorSelector(app.GridLayout11_2);
            app.DropdownFontColor.ValueChangedFcn = createCallbackFcn(app, @DropdownFontColorValueChanged, true);
            app.ListboxTab = uitab(app.TabGroup);
            app.ListboxTab.Title = 'Listbox';
            app.GridLayout12 = uigridlayout(app.ListboxTab);
            app.BackgroundColorLabel_4 = uilabel(app.GridLayout12);
            app.BackgroundColorLabel_4.Text = 'Background Color';
            app.ListboxBgColor = wt.ColorSelector(app.GridLayout12);
            app.ListboxBgColor.ValueChangedFcn = createCallbackFcn(app, @ListboxBgColorValueChanged, true);
            app.FontColorLabel_4 = uilabel(app.GridLayout12);
            app.FontColorLabel_4.Text = 'Font Color';
            app.ListboxFontColor = wt.ColorSelector(app.GridLayout12);
            app.ListboxFontColor.ValueChangedFcn = createCallbackFcn(app, @ListboxFontColorValueChanged, true);
            app.SliderTab = uitab(app.TabGroup);
            app.SliderTab.Title = 'Slider';
            app.GridLayout19 = uigridlayout(app.SliderTab);
            app.SliderFontColor = wt.ColorSelector(app.GridLayout19);
            app.SliderFontColor.ValueChangedFcn = createCallbackFcn(app, @SliderFontColorValueChanged, true);
            app.FontColorLabel_7 = uilabel(app.GridLayout19);
            app.FontColorLabel_7.Text = 'Font Color';
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Table';
            app.GridLayout12_2 = uigridlayout(app.Table);
            app.BackgroundColor1Label = uilabel(app.GridLayout12_2);
            app.BackgroundColor1Label.Text = 'Background Color 1';
            app.TableBg1Color = wt.ColorSelector(app.GridLayout12_2);
            app.TableBg1Color.ValueChangedFcn = createCallbackFcn(app, @TableBg1ColorValueChanged, true);
            app.BackgroundColor2Label = uilabel(app.GridLayout12_2);
            app.BackgroundColor2Label.Text = 'Background Color 2';
            app.TableBg2Color = wt.ColorSelector(app.GridLayout12_2);
            app.TableBg2Color.ValueChangedFcn = createCallbackFcn(app, @TableBg2ColorValueChanged, true);
            app.FontColorLabel_8 = uilabel(app.GridLayout12_2);
            app.FontColorLabel_8.Text = 'Font Color';
            app.TableFontColor = wt.ColorSelector(app.GridLayout12_2);
            app.TableFontColor.ValueChangedFcn = createCallbackFcn(app, @TableFontColorValueChanged, true);
            app.AxesTab = uitab(app.TabGroup);
            app.AxesTab.Title = 'Axes';
            app.GridLayout13 = uigridlayout(app.AxesTab);
            app.BackgroundColorLabel_5 = uilabel(app.GridLayout13);
            app.BackgroundColorLabel_5.Text = 'Background Color';
            app.AxesBgColor = wt.ColorSelector(app.GridLayout13);
            app.AxesBgColor.ValueChangedFcn = createCallbackFcn(app, @AxesBgColorValueChanged, true);
            app.GridColorLabel = uilabel(app.GridLayout13);
            app.GridColorLabel.Text = 'Grid Color';
            app.AxesGridColor = wt.ColorSelector(app.GridLayout13);
            app.AxesGridColor.ValueChangedFcn = createCallbackFcn(app, @AxesGridColorValueChanged, true);
            app.GridLayout16 = uigridlayout(app.GridLayout15);
            app.ThemeNameLabel = uilabel(app.GridLayout16);
            app.ThemeNameLabel.Text = 'Theme Name';
            app.ThemeNameEdit = uieditfield(app.GridLayout16, 'text');
            app.ThemeNameEdit.ValueChangingFcn = createCallbackFcn(app, @ThemeNameEditValueChanging, true);
            app.ThemeNameEdit.Tooltip = {'Sets the name of the theme.'};
            app.GridLayout17 = uigridlayout(app.GridLayout15);
            app.ThemeDescriptionLabel = uilabel(app.GridLayout17);
            app.ThemeDescriptionLabel.Text = 'Theme Description';
            app.ThemeDescEdit = uieditfield(app.GridLayout17, 'text');
            app.ThemeDescEdit.ValueChangedFcn = createCallbackFcn(app, @ThemeDescEditValueChanged, true);
            app.ThemeDescEdit.Tooltip = {'Sets the description of the theme.  Optional.'};
            app.GridLayout18 = uigridlayout(app.GridLayout15);
            app.ActiveThemeCheckBox = uicheckbox(app.GridLayout18);
            app.ActiveThemeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActiveThemeCheckBoxValueChanged, true);
            app.ActiveThemeCheckBox.Tooltip = {'Check this box to set the selected theme as the active theme for KSPTOT.'};
            app.ActiveThemeCheckBox.Text = 'Active Theme';
            app.GridLayout14 = uigridlayout(app.GridLayout);
            app.titleText = uilabel(app.GridLayout14);
            app.titleText.Tag = 'titleText';
            app.titleText.Text = 'App Theme Editor';
            app.Image = uiimage(app.GridLayout14);
            app.Image.ImageSource = 'paint-swatch.png';
            % Show the figure after all components are created
            app.AppThemeEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppThemeEditorGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AppThemeEditorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.AppThemeEditorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AppThemeEditorUIFigure)
        end
    end
end