classdef OptimalTwoBurnOrbitChange_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptimalTwoBurnOrbitChangeGUI    matlab.ui.Figure
        fileMenu                        matlab.ui.container.Menu
        generateReport                  matlab.ui.container.Menu
        uitoolbar1                      matlab.ui.container.Toolbar
        uitoggletool1                   matlab.ui.container.toolbar.ToggleTool
        uitoggletool2                   matlab.ui.container.toolbar.ToggleTool
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        computeOptimal2BurnManButton    matlab.ui.control.Button
        dvMan2InfoText                  matlab.ui.control.TextArea
        text25                          matlab.ui.control.Label
        dvMan1InfoText                  matlab.ui.control.TextArea
        text24                          matlab.ui.control.Label
        xfrOrbitText                    matlab.ui.control.TextArea
        text23                          matlab.ui.control.Label
        GridLayout5                     matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        uipanel6                        matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        burn2ParamBtnGrp                matlab.ui.container.ButtonGroup
        text30                          matlab.ui.control.Label
        fixedTp2Text                    matlab.ui.control.EditField
        fixTp2RadioBtn                  matlab.ui.control.RadioButton
        fixTA2RadioBtn                  matlab.ui.control.RadioButton
        text32                          matlab.ui.control.Label
        fixLoc2Checkbox                 matlab.ui.control.CheckBox
        uipanel5                        matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        burn1ParamBtnGrp                matlab.ui.container.ButtonGroup
        text28                          matlab.ui.control.Label
        fixedTp1Text                    matlab.ui.control.EditField
        fixTp1RadioBtn                  matlab.ui.control.RadioButton
        fixTA1RadioBtn                  matlab.ui.control.RadioButton
        text31                          matlab.ui.control.Label
        fixLoc1Checkbox                 matlab.ui.control.CheckBox
        Panel                           matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        orbitDispAxes                   matlab.ui.control.UIAxes
        GridLayout4                     matlab.ui.container.GridLayout
        uipanel4                        matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        text14                          matlab.ui.control.Label
        text13                          matlab.ui.control.Label
        text12                          matlab.ui.control.Label
        text11                          matlab.ui.control.Label
        finOrbitARGText                 matlab.ui.control.EditField
        finOrbitRAANText                matlab.ui.control.EditField
        finOrbitINCText                 matlab.ui.control.EditField
        finOrbitECCText                 matlab.ui.control.EditField
        finOrbitSMAText                 matlab.ui.control.EditField
        text19                          matlab.ui.control.Label
        text18                          matlab.ui.control.Label
        text17                          matlab.ui.control.Label
        text16                          matlab.ui.control.Label
        text15                          matlab.ui.control.Label
        uipanel3                        matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        text5                           matlab.ui.control.Label
        text4                           matlab.ui.control.Label
        text3                           matlab.ui.control.Label
        text2                           matlab.ui.control.Label
        iniOrbitARGText                 matlab.ui.control.EditField
        iniOrbitRAANText                matlab.ui.control.EditField
        iniOrbitINCText                 matlab.ui.control.EditField
        iniOrbitECCText                 matlab.ui.control.EditField
        iniOrbitSMAText                 matlab.ui.control.EditField
        text10                          matlab.ui.control.Label
        text9                           matlab.ui.control.Label
        text8                           matlab.ui.control.Label
        text7                           matlab.ui.control.Label
        text6                           matlab.ui.control.Label
        uipanel2                        matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        orbitingAboutCombo              matlab.ui.control.DropDown
        text1                           matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        titleText                       matlab.ui.control.Label
        iniOrbitContMenu                matlab.ui.container.ContextMenu
        getINIOrbitFromSFSFile          matlab.ui.container.Menu
        getOrbitFromKSP                 matlab.ui.container.Menu
        getOrbitFromKSPActiveVesselMenu  matlab.ui.container.Menu
        copyOrbitToClipboardMenu1       matlab.ui.container.Menu
        pasteOrbitFromClipboardMenu1    matlab.ui.container.Menu
        iniOrbitContMenuWithGetDateTime  matlab.ui.container.ContextMenu
        enterUTAsDateTime               matlab.ui.container.Menu
        getINIOrbitFromSFSFile_1        matlab.ui.container.Menu
        getOrbitFromKSP_1               matlab.ui.container.Menu
        getOrbitFromKSPActiveVesselMenu_1  matlab.ui.container.Menu
        copyOrbitToClipboardMenu1_1     matlab.ui.container.Menu
        pasteOrbitFromClipboardMenu1_1  matlab.ui.container.Menu
        finalOrbitContMenu              matlab.ui.container.ContextMenu
        getFinalOrbitFromSFSFile        matlab.ui.container.Menu
        getOrbitFromKSP_2               matlab.ui.container.Menu
        getOrbitFromKSPActiveVesselMenu_2  matlab.ui.container.Menu
        copyOrbitToClipboardMenu2       matlab.ui.container.Menu
        pasteOrbitFromClipboardMenu2    matlab.ui.container.Menu
        uploadManeuver2KSP              matlab.ui.container.ContextMenu
        uploadManeuver                  matlab.ui.container.Menu
    end

    
    methods (Access = private)
        function edit25_CreateFcn(~, hObject, ~, ~)
            % hObject    handle to meanAnomText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit26_CreateFcn(~, hObject, ~, ~)
            % hObject    handle to epochText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function epochText_CreateFcn(~, hObject, ~, ~)
            % hObject    handle to epochText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function getFinalOrbitFromSFSFile_Callback(~, ~, ~, handles)
            % hObject    handle to getFinalOrbitFromSFSFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                userData = get(handles.OptimalTwoBurnOrbitChangeGUI,'UserData');
                mainGUIHandle = userData{1,1};
                refBodyID = orbitPanelGetOrbitFromSFSContextCallBack(mainGUIHandle, handles.finOrbitSMAText, handles.finOrbitECCText, handles.finOrbitINCText, handles.finOrbitRAANText, handles.finOrbitARGText);
            
                if(~isempty(refBodyID) && isnumeric(refBodyID))
                    userData = get(handles.OptimalTwoBurnOrbitChangeGUI,'UserData');
                    mainUserData = get(userData{1,1},'UserData');
                    celBodyData = mainUserData{1,1};
            
                    bodyInfo = getBodyInfoByNumber(refBodyID, celBodyData);
                    value = findValueFromComboBox(bodyInfo.name, handles.orbitingAboutCombo);
                    set(handles.orbitingAboutCombo,'Value',value);
                    
                end
        end
        
        function meanAnomText_CreateFcn(~, hObject, ~, ~)
            % hObject    handle to meanAnomText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function popupmenu2_CreateFcn(~, hObject, ~, ~)
            % hObject    handle to popupmenu2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function useMAEpochCheckbox_Callback(~, hObject, ~, handles)
            % hObject    handle to useMAEpochCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of useMAEpochCheckbox
            if(get(hObject,'Value')==1)
                set(handles.meanAnomText, 'Enable', 'on');
                set(handles.epochText, 'Enable', 'on');
            else
                set(handles.meanAnomText, 'Enable', 'off');
                set(handles.epochText, 'Enable', 'off');
            end
        end
        
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool1, app.uitoggletool2];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.OptimalTwoBurnOrbitChangeGUI, 'off')
            rotate3d(app.OptimalTwoBurnOrbitChangeGUI, 'off');
            pan(app.OptimalTwoBurnOrbitChangeGUI, 'off');
            zoom(app.OptimalTwoBurnOrbitChangeGUI,'off');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function OptimalTwoBurnOrbitChange_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to OptimalTwoBurnOrbitChange (see VARARGIN)
            
            % Choose default command line output for OptimalTwoBurnOrbitChange
            handles.output = hObject;
            centerUIFigure(app.OptimalTwoBurnOrbitChangeGUI);
            applySelectedThemeToApp(app);
            
            % Update handles structure
            guidata(hObject, handles);
            
            %Possible fix for people with display issues.
%             checkForCharUnitsInGUI(hObject);
            
            mainGUIHandle = varargin{1};
            mainUserData = get(mainGUIHandle, 'UserData');
            userData = cell(10);
            userData{1,1} = mainGUIHandle;
            set(hObject, 'UserData', userData);
            
            userData = get(mainGUIHandle,'UserData');
            celBodyData = userData{1,1};
            
            % [~, ~, allNames] = genCBArriveDepartComboBoxStrs(mainGUIHandle);
            allNames = ma_getSortedBodyNames(celBodyData);
            allNamesComboString = char(cap1stLetter(allNames));
            set(handles.orbitingAboutCombo,'String',allNamesComboString);
            set(handles.orbitingAboutCombo,'Value',5);
            
            hold on;
            axes(app.orbitDispAxes);
            set(app.orbitDispAxes,'XTickLabel',[]);
            set(app.orbitDispAxes,'YTickLabel',[]);
            set(app.orbitDispAxes,'ZTickLabel',[]);
            grid on;
            view(3);
            
            celBodyData = mainUserData{1,1};
            contents = cellstr(get(handles.orbitingAboutCombo,'String'));
            bodyName = contents{get(handles.orbitingAboutCombo,'Value')};
            bodyInfo = celBodyData.(strtrim(lower(bodyName)));
%             dRad = bodyInfo.radius;
%             [X,Y,Z] = sphere(30);
%             surf(dRad*X,dRad*Y,dRad*Z);
%             colormap(bodyInfo.bodycolor);
            
            ma_initOrbPlot(app.OptimalTwoBurnOrbitChangeGUI, app.orbitDispAxes, bodyInfo);

%             axis equal;
            hold off;
        end

        % Button pushed function: computeOptimal2BurnManButton
        function computeOptimal2BurnManButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to computeOptimal2BurnManButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            userData = get(handles.OptimalTwoBurnOrbitChangeGUI,'UserData');
            mainGUIUserData = get(userData{1,1},'UserData');
            celBodyData = mainGUIUserData{1,1};
            
            errMsg = {};
            
            iniOrbit(2) = str2double(get(handles.iniOrbitECCText, 'String'));
            enteredStr = get(handles.iniOrbitECCText,'String');
            numberName = 'Initial orbit eccentricity';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(iniOrbit(2), numberName, lb, ub, isInt, errMsg, enteredStr);
            if(not(isempty(errMsg)))
                msgbox(errMsg,'Errors were found in departure burn calculation inputs','error');
                return;
            end
            
            finOrbit(2) = str2double(get(handles.finOrbitECCText, 'String'));
            enteredStr = get(handles.finOrbitECCText,'String');
            numberName = 'Final orbit eccentricity';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(finOrbit(2), numberName, lb, ub, isInt, errMsg, enteredStr);
            if(not(isempty(errMsg)))
                msgbox(errMsg,'Errors were found in departure burn calculation inputs','error');
                return;
            end
            
            iniOrbit(1) = str2double(get(handles.iniOrbitSMAText, 'String'));
            enteredStr = get(handles.iniOrbitSMAText,'String');
            if(iniOrbit(2) < 1.0)
                lb = 1;
                ub = Inf;
                numberName = 'Initial eccentric orbit semi-major axis';
            else
                lb = -Inf;
                ub = -1;
                numberName = 'Initial hyperbolic orbit semi-major axis';
            end
            isInt = false;
            errMsg = validateNumber(iniOrbit(1), numberName, lb, ub, isInt, errMsg, enteredStr);
            
            iniOrbit(3) = str2double(get(handles.iniOrbitINCText, 'String'));
            enteredStr = get(handles.iniOrbitINCText,'String');
            numberName = 'Initial orbit inclination';
            lb = 0;
            ub = 180;
            isInt = false;
            errMsg = validateNumber(iniOrbit(3), numberName, lb, ub, isInt, errMsg, enteredStr);
            iniOrbit(3)=iniOrbit(3)*pi/180;
            
            iniOrbit(4) = str2double(get(handles.iniOrbitRAANText, 'String'));
            enteredStr = get(handles.iniOrbitRAANText,'String');
            numberName = 'Initial orbit RAAN';
            lb = 0;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(iniOrbit(4), numberName, lb, ub, isInt, errMsg, enteredStr);
            iniOrbit(4)=iniOrbit(4)*pi/180;
            
            iniOrbit(5) = str2double(get(handles.iniOrbitARGText, 'String'));
            enteredStr = get(handles.iniOrbitARGText,'String');
            numberName = 'Initial orbit argument of periapse';
            lb = 0;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(iniOrbit(5), numberName, lb, ub, isInt, errMsg, enteredStr);
            iniOrbit(5)=iniOrbit(5)*pi/180;
            
            finOrbit(1) = str2double(get(handles.finOrbitSMAText, 'String'));
            enteredStr = get(handles.finOrbitSMAText,'String');
            if(finOrbit(2) < 1.0)
                lb = 1;
                ub = Inf;
                numberName = 'Final eccentric orbit semi-major axis';
            else
                lb = -Inf;
                ub = -1;
                numberName = 'Final hyperbolic orbit semi-major axis';
            end
            isInt = false;
            errMsg = validateNumber(finOrbit(1), numberName, lb, ub, isInt, errMsg, enteredStr);
            
            finOrbit(3) = str2double(get(handles.finOrbitINCText, 'String'));
            enteredStr = get(handles.finOrbitINCText,'String');
            numberName = 'Final orbit inclination';
            lb = 0;
            ub = 180;
            isInt = false;
            errMsg = validateNumber(finOrbit(3), numberName, lb, ub, isInt, errMsg, enteredStr);
            finOrbit(3)=finOrbit(3)*pi/180;
            
            finOrbit(4) = str2double(get(handles.finOrbitRAANText, 'String'));
            enteredStr = get(handles.finOrbitRAANText,'String');
            numberName = 'Final orbit RAAN';
            lb = 0;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(finOrbit(4), numberName, lb, ub, isInt, errMsg, enteredStr);
            finOrbit(4)=finOrbit(4)*pi/180;
            
            finOrbit(5) = str2double(get(handles.finOrbitARGText, 'String'));
            enteredStr = get(handles.finOrbitARGText,'String');
            numberName = 'Final orbit argument of periapse';
            lb = 0;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(finOrbit(5), numberName, lb, ub, isInt, errMsg, enteredStr);
            finOrbit(5)=finOrbit(5)*pi/180;
            
            fixLoc1 = get(handles.fixLoc1Checkbox,'Value');
            fixLoc2 = get(handles.fixLoc2Checkbox,'Value');
            
            if(fixLoc1==1)
                burn1ParamsSel = get(get(handles.burn1ParamBtnGrp,'SelectedObject'),'Tag');
                if(strcmpi(burn1ParamsSel, 'fixTp1RadioBtn'))
                    fixedTp1 = str2double(get(handles.fixedTp1Text,'String'));
                    enteredStr = get(handles.fixedTp1Text,'String');
                    numberName = 'Burn 1 Location (Delta-Time from Periapse)';
                    lb = -Inf;
                    ub = Inf;
                    isInt = false;
                    errMsg = validateNumber(fixedTp1, numberName, lb, ub, isInt, errMsg, enteredStr);
                else
                    fixedTA1 = str2double(get(handles.fixedTA1Text,'String'));
                    enteredStr = get(handles.fixedTA1Text,'String');
                    numberName = 'Burn 1 Location (True Anomaly)';
                    lb = 0;
                    ub = 360;
                    isInt = false;
                    errMsg = validateNumber(fixedTA1, numberName, lb, ub, isInt, errMsg, enteredStr);
                    fixedTA1 = AngleZero2Pi(deg2rad(fixedTA1));
                end
            end
            
            if(fixLoc2==1)
                burn2ParamsSel = get(get(handles.burn2ParamBtnGrp,'SelectedObject'),'Tag');
                if(strcmpi(burn2ParamsSel, 'fixTp2RadioBtn'))
                    fixedTp2 = str2double(get(handles.fixedTp2Text,'String'));
                    enteredStr = get(handles.fixedTp2Text,'String');
                    numberName = 'Burn 2 Location (Delta-Time from Periapse)';
                    lb = -Inf;
                    ub = Inf;
                    isInt = false;
                    errMsg = validateNumber(fixedTp2, numberName, lb, ub, isInt, errMsg, enteredStr);
                else
                    fixedTA2 = str2double(get(handles.fixedTA2Text,'String'));
                    enteredStr = get(handles.fixedTA2Text,'String');
                    numberName = 'Burn 2 Location (True Anomaly)';
                    lb = 0;
                    ub = 360;
                    isInt = false;
                    errMsg = validateNumber(fixedTA2, numberName, lb, ub, isInt, errMsg, enteredStr);
                    fixedTA2 = AngleZero2Pi(deg2rad(fixedTA2));
                end
            end
            
            if(isempty(errMsg))
                try
                    contents = cellstr(get(handles.orbitingAboutCombo,'String'));
                    bodyName = contents{get(handles.orbitingAboutCombo,'Value')};
                    bodyInfo = celBodyData.(strtrim(lower(bodyName)));
                    gmuXfr = bodyInfo.gm;
            
                    %initial conditions
                    x0(1) = 0.0;
                    x0(2) = 0.1;
                    x0(3) = 500;
            
                    parentBodyInfo = bodyInfo.getParBodyInfo(celBodyData);
            
                    [iniLB, iniUB, iniPeriod] = getOrbitTAPlotBnds(bodyInfo, parentBodyInfo, iniOrbit);
                    [finLB, finUB, finPeriod] = getOrbitTAPlotBnds(bodyInfo, parentBodyInfo, finOrbit);
            
                    lb = [iniLB, finLB, 0.1];
                    ub = [iniUB, finUB, 2*max(iniPeriod,finPeriod)];
            
                    objFunc = @(x) twoBurnOrbitChangeObjFunc(x, iniOrbit, finOrbit, gmuXfr);
                    nonlcon = [];
                    Aeq = [];
                    beq = [];
                    if(fixLoc1==0 && fixLoc2==0)
                        nonlcon = [];
                    else
                        if(fixLoc1==1)
                            if(strcmpi(burn1ParamsSel, 'fixTp1RadioBtn'))
                                fixedTA1 = getTAFromTimePastPeriapse(fixedTp1, iniOrbit(1), iniOrbit(2), gmuXfr);
                            end
                            Aeq(end+1,:) = [1 0 0];
                            beq(end+1) = fixedTA1;
                            Aeq(end+1,:) = [-1 0 0];
                            beq(end+1) = -fixedTA1;
                        end
                        if(fixLoc2==1)
                            if(strcmpi(burn2ParamsSel, 'fixTp2RadioBtn'))
                                fixedTA2 = getTAFromTimePastPeriapse(fixedTp2, finOrbit(1), finOrbit(2), gmuXfr);
                            end
                            Aeq(end+1,:) = [0 1 0];
                            beq(end+1) = fixedTA2;
                            Aeq(end+1,:) = [0 -1 0];
                            beq(end+1) = -fixedTA2;
                        end
                    end
            
                    hUiProgDlg = uiprogressdlg(app.OptimalTwoBurnOrbitChangeGUI, 'Title','Optimal Two Burn Orbit Change');
                    
                    [xOpt,~] = multiStartCommonRun('Finding Optimal 2 Burn Orbit Change Maneuvers...', 20, objFunc, x0, Aeq,beq, lb, ub, nonlcon, hUiProgDlg);
                    [~, deltaV1, deltaV2, deltaV1R, deltaV2R, xfrOrbit, deltaV1NTW, deltaV2NTW] = objFunc(xOpt);
            
                    burnTAINIOrbit = xOpt(1);
                    burnTAXfrOrbit = xfrOrbit(7);
            
                    form = '%9.3f';
                    paddLen = 32;
                    xferOrbitText{1} = ['Transfer Orbit about ', cap1stLetter(lower(bodyName))];
                    printXferOrbitToTextbox(handles.xfrOrbitText, xferOrbitText, xfrOrbit, form, paddLen)
            
                    paddLen = 29;
                    form = '%9.5f';
                    burn1InfoText{1} = 'Transfer Orbit Injection Burn';
                    burn1InfoText{end+1} = '(Relative to initial orbit)';
                    burn1InfoTextStr = printDVManeuverInfoToTextbox(handles.dvMan1InfoText, burn1InfoText, deltaV1NTW, burnTAINIOrbit, iniOrbit, gmuXfr, form, paddLen);
            
                    paddLen = 29;
                    form = '%9.5f';
                    burn2InfoText{1} = 'Final Orbit Injection Burn';
                    burn2InfoText{end+1} = '(Relative to transfer orbit)';
                    burn2InfoTextStr = printDVManeuverInfoToTextbox(handles.dvMan2InfoText, burn2InfoText, deltaV2NTW, burnTAXfrOrbit, xfrOrbit, gmuXfr, form, paddLen);
            
                    plot2BurnOrbitChange(handles.orbitDispAxes, bodyInfo, iniOrbit, finOrbit, xfrOrbit, deltaV1, deltaV1R, deltaV2, deltaV2R, celBodyData);
            
                    results = struct();
            
                    results.burn1InfoTextStr = burn1InfoTextStr;
                    results.burn2InfoTextStr = burn2InfoTextStr;
                    results.bodyInfo = bodyInfo;
                    results.iniOrbit = iniOrbit;
                    results.finOrbit = finOrbit;
                    results.xfrOrbit = xfrOrbit;
                    results.deltaV1 = deltaV1;
                    results.deltaV1R = deltaV1R;
                    results.deltaV2 = deltaV2;
                    results.deltaV2R = deltaV2R;
            
                    userData = get(handles.OptimalTwoBurnOrbitChangeGUI,'UserData');
                    userData{2,1} = results;
                    set(handles.OptimalTwoBurnOrbitChangeGUI,'UserData',userData);

                    applySelectedThemeToApp(app);
                catch ME
                    msgbox(ME.message);
                end
            else
                msgbox(errMsg,'Errors were found in orbit change calculation inputs','error');
            end
        end

        % Menu selected function: copyOrbitToClipboardMenu1, 
        % ...and 1 other component
        function copyOrbitToClipboardMenu1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to copyOrbitToClipboardMenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                userData = get(handles.OptimalTwoBurnOrbitChangeGUI,'UserData');
                mainGUIUserData = get(userData{1,1},'UserData');
                celBodyData = mainGUIUserData{1,1};
            
                contents = cellstr(get(handles.orbitingAboutCombo,'String'));
                bodyName = contents{get(handles.orbitingAboutCombo,'Value')};
                bodyInfo = celBodyData.(strtrim(lower(bodyName)));
            
                copyOrbitToClipboardFromText([], handles.iniOrbitSMAText, handles.iniOrbitECCText, ...
                                             handles.iniOrbitINCText, handles.iniOrbitRAANText, handles.iniOrbitARGText, ...
                                             [], true, bodyInfo.id);
        end

        % Menu selected function: copyOrbitToClipboardMenu2
        function copyOrbitToClipboardMenu2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to copyOrbitToClipboardMenu2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                userData = get(handles.OptimalTwoBurnOrbitChangeGUI,'UserData');
                mainGUIUserData = get(userData{1,1},'UserData');
                celBodyData = mainGUIUserData{1,1};
            
                contents = cellstr(get(handles.orbitingAboutCombo,'String'));
                bodyName = contents{get(handles.orbitingAboutCombo,'Value')};
                bodyInfo = celBodyData.(strtrim(lower(bodyName)));
            
                copyOrbitToClipboardFromText([], handles.finOrbitSMAText, handles.finOrbitECCText, ...
                                             handles.finOrbitINCText, handles.finOrbitRAANText, handles.finOrbitARGText, ...
                                             [], true, bodyInfo.id);
        end

        % Menu selected function: enterUTAsDateTime
        function enterUTAsDateTime_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to enterUTAsDateTime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
%             secUT = enterUTAsDateTimeGUI(str2double(get(gco, 'Value')));

            output2 = AppDesignerGUIOutput({-1});
            enterUTAsDateTimeGUI_App(str2double(get(gco, 'Value')), output2);
            secUT = output2.output{1};

            if(secUT >= 0)
                set(gco, 'Value', num2str(secUT));
                epochText_Callback(app, handles.epochText, eventdata, handles);
            end
        end

        % Value changed function: finOrbitARGText
        function finOrbitARGText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to finOrbitARGText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of finOrbitARGText as text
            %        str2double(get(hObject,'String')) returns contents of finOrbitARGText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: finOrbitECCText
        function finOrbitECCText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to finOrbitECCText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of finOrbitECCText as text
            %        str2double(get(hObject,'String')) returns contents of finOrbitECCText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: finOrbitINCText
        function finOrbitINCText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to finOrbitINCText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of finOrbitINCText as text
            %        str2double(get(hObject,'String')) returns contents of finOrbitINCText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: finOrbitRAANText
        function finOrbitRAANText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to finOrbitRAANText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of finOrbitRAANText as text
            %        str2double(get(hObject,'String')) returns contents of finOrbitRAANText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: finOrbitSMAText
        function finOrbitSMAText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to finOrbitSMAText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of finOrbitSMAText as text
            %        str2double(get(hObject,'String')) returns contents of finOrbitSMAText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: fixLoc1Checkbox
        function fixLoc1Checkbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to fixLoc1Checkbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of fixLoc1Checkbox
            if(get(hObject,'Value')==1)
                set(handles.fixedTA1Text, 'Enable', 'on');
                set(handles.fixTA1RadioBtn, 'Enable', 'on');
                set(handles.fixedTp1Text, 'Enable', 'on');
                set(handles.fixTp1RadioBtn, 'Enable', 'on');
            elseif(get(hObject,'Value')==0)
                set(handles.fixedTA1Text, 'Enable', 'off');
                set(handles.fixTA1RadioBtn, 'Enable', 'off');
                set(handles.fixedTp1Text, 'Enable', 'off');
                set(handles.fixTp1RadioBtn, 'Enable', 'off');
            end
        end

        % Value changed function: fixLoc2Checkbox
        function fixLoc2Checkbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to fixLoc2Checkbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of fixLoc2Checkbox
            if(get(hObject,'Value')==1)
                set(handles.fixedTA2Text, 'Enable', 'on');
                set(handles.fixTA2RadioBtn, 'Enable', 'on');
                set(handles.fixedTp2Text, 'Enable', 'on');
                set(handles.fixTp2RadioBtn, 'Enable', 'on');
            elseif(get(hObject,'Value')==0)
                set(handles.fixedTA2Text, 'Enable', 'off');
                set(handles.fixTA2RadioBtn, 'Enable', 'off');
                set(handles.fixedTp2Text, 'Enable', 'off');
                set(handles.fixTp2RadioBtn, 'Enable', 'off');
            end
        end

        % Callback function
        function fixedTA1Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to fixedTA1Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of fixedTA1Text as text
            %        str2double(get(hObject,'String')) returns contents of fixedTA1Text as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Callback function
        function fixedTA2Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to fixedTA2Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of fixedTA2Text as text
            %        str2double(get(hObject,'String')) returns contents of fixedTA2Text as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Menu selected function: generateReport
        function generateReport_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to generateReport (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [~, handlesR] = genericReport(1, 'DOUBLE DELTA-V MANEUVER', 'TWO BURN ORBIT CHANGE');
            userData = get(handles.OptimalTwoBurnOrbitChangeGUI,'UserData');
            results = userData{2,1};
            
            mainUserData = get(userData{1,1}, 'UserData');
            celBodyData = mainUserData{1,1};
            
            if(~isempty(results))
                set(handlesR.leftTextBox,'String',results.burn1InfoTextStr);
                set(handlesR.rightTextBox,'String',results.burn2InfoTextStr);
                plot2BurnOrbitChange(handlesR.leftAxis, results.bodyInfo, results.iniOrbit, results.finOrbit, results.xfrOrbit, results.deltaV1, results.deltaV1R, results.deltaV2, results.deltaV2R, celBodyData);
            end
        end

        % Menu selected function: getFinalOrbitFromSFSFile, 
        % ...and 1 other component
        function getFinalOrbitFromKSPActiveVesselMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to getOrbitFromKSPActiveVesselMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                refBodyID = orbitPanelGetOrbitFromKSPTOTConnectActiveVesselCallBack(handles.finOrbitSMAText, handles.finOrbitECCText, handles.finOrbitINCText, handles.finOrbitRAANText, handles.finOrbitARGText);
            
                if(~isempty(refBodyID) && isnumeric(refBodyID))
                    userData = get(handles.OptimalTwoBurnOrbitChangeGUI,'UserData');
                    mainUserData = get(userData{1,1},'UserData');
                    celBodyData = mainUserData{1,1};
            
                    bodyInfo = getBodyInfoByNumber(refBodyID, celBodyData);
                    value = findValueFromComboBox(bodyInfo.name, handles.orbitingAboutCombo);
                    set(handles.orbitingAboutCombo,'Value',value);
                end
        end

        % Menu selected function: getOrbitFromKSP_2
        function getFinalOrbitFromKSPTOTConnect_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to getFinalOrbitFromSFSFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                refBodyID = orbitPanelGetOrbitFromKSPTOTConnectCallBack(handles.finOrbitSMAText, handles.finOrbitECCText, handles.finOrbitINCText, handles.finOrbitRAANText, handles.finOrbitARGText);
            
                if(~isempty(refBodyID) && isnumeric(refBodyID))
                    userData = get(handles.OptimalTwoBurnOrbitChangeGUI,'UserData');
                    mainUserData = get(userData{1,1},'UserData');
                    celBodyData = mainUserData{1,1};
            
                    bodyInfo = getBodyInfoByNumber(refBodyID, celBodyData);
                    value = findValueFromComboBox(bodyInfo.name, handles.orbitingAboutCombo);
                    set(handles.orbitingAboutCombo,'Value',value);
                    
                end
        end

        % Menu selected function: getOrbitFromKSP, getOrbitFromKSP_1
        function getINIOrbitFromKSPTOTConnect_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to getINIOrbitFromSFSFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                refBodyID = orbitPanelGetOrbitFromKSPTOTConnectCallBack(handles.iniOrbitSMAText, handles.iniOrbitECCText, handles.iniOrbitINCText, handles.iniOrbitRAANText, handles.iniOrbitARGText);
            
                if(~isempty(refBodyID) && isnumeric(refBodyID))
                    userData = get(handles.OptimalTwoBurnOrbitChangeGUI,'UserData');
                    mainUserData = get(userData{1,1},'UserData');
                    celBodyData = mainUserData{1,1};
            
                    bodyInfo = getBodyInfoByNumber(refBodyID, celBodyData);
                    value = findValueFromComboBox(bodyInfo.name, handles.orbitingAboutCombo);
                    set(handles.orbitingAboutCombo,'Value',value);
                    
                end
        end

        % Menu selected function: getINIOrbitFromSFSFile, 
        % ...and 1 other component
        function getINIOrbitFromSFSFile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to getINIOrbitFromSFSFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                userData = get(handles.OptimalTwoBurnOrbitChangeGUI,'UserData');
                mainGUIHandle = userData{1,1};
                refBodyID = orbitPanelGetOrbitFromSFSContextCallBack(mainGUIHandle, handles.iniOrbitSMAText, handles.iniOrbitECCText, handles.iniOrbitINCText, handles.iniOrbitRAANText, handles.iniOrbitARGText);
            
                if(~isempty(refBodyID) && isnumeric(refBodyID))
                    userData = get(handles.OptimalTwoBurnOrbitChangeGUI,'UserData');
                    mainUserData = get(userData{1,1},'UserData');
                    celBodyData = mainUserData{1,1};
            
                    bodyInfo = getBodyInfoByNumber(refBodyID, celBodyData);
                    value = findValueFromComboBox(bodyInfo.name, handles.orbitingAboutCombo);
                    set(handles.orbitingAboutCombo,'Value',value);
                    
                end
        end

        % Menu selected function: getOrbitFromKSPActiveVesselMenu, 
        % ...and 1 other component
        function getOrbitFromKSPActiveVesselMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to getOrbitFromKSPActiveVesselMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                refBodyID = orbitPanelGetOrbitFromKSPTOTConnectActiveVesselCallBack(handles.iniOrbitSMAText, handles.iniOrbitECCText, handles.iniOrbitINCText, handles.iniOrbitRAANText, handles.iniOrbitARGText);
            
                if(~isempty(refBodyID) && isnumeric(refBodyID))
                    userData = get(handles.OptimalTwoBurnOrbitChangeGUI,'UserData');
                    mainUserData = get(userData{1,1},'UserData');
                    celBodyData = mainUserData{1,1};
            
                    bodyInfo = getBodyInfoByNumber(refBodyID, celBodyData);
                    value = findValueFromComboBox(bodyInfo.name, handles.orbitingAboutCombo);
                    set(handles.orbitingAboutCombo,'Value',value);
                    
                end
        end

        % Value changed function: iniOrbitARGText
        function iniOrbitARGText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to iniOrbitARGText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of iniOrbitARGText as text
            %        str2double(get(hObject,'String')) returns contents of iniOrbitARGText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: iniOrbitECCText
        function iniOrbitECCText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to iniOrbitECCText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of iniOrbitECCText as text
            %        str2double(get(hObject,'String')) returns contents of iniOrbitECCText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: iniOrbitINCText
        function iniOrbitINCText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to iniOrbitINCText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of iniOrbitINCText as text
            %        str2double(get(hObject,'String')) returns contents of iniOrbitINCText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: iniOrbitRAANText
        function iniOrbitRAANText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to iniOrbitRAANText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of iniOrbitRAANText as text
            %        str2double(get(hObject,'String')) returns contents of iniOrbitRAANText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: iniOrbitSMAText
        function iniOrbitSMAText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to iniOrbitSMAText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of iniOrbitSMAText as text
            %        str2double(get(hObject,'String')) returns contents of iniOrbitSMAText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Menu selected function: pasteOrbitFromClipboardMenu1, 
        % ...and 1 other component
        function pasteOrbitFromClipboardMenu1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pasteOrbitFromClipboardMenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                userData = get(handles.OptimalTwoBurnOrbitChangeGUI,'UserData');
                mainGUIUserData = get(userData{1,1},'UserData');
                celBodyData = mainGUIUserData{1,1};
            
                pasteOrbitFromClipboard([], handles.iniOrbitSMAText, handles.iniOrbitECCText, ...
                                             handles.iniOrbitINCText, handles.iniOrbitRAANText, handles.iniOrbitARGText, ...
                                             [], true, handles.orbitingAboutCombo, celBodyData);
        end

        % Menu selected function: pasteOrbitFromClipboardMenu2
        function pasteOrbitFromClipboardMenu2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pasteOrbitFromClipboardMenu2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                userData = get(handles.OptimalTwoBurnOrbitChangeGUI,'UserData');
                mainGUIUserData = get(userData{1,1},'UserData');
                celBodyData = mainGUIUserData{1,1};
            
                pasteOrbitFromClipboard([], handles.finOrbitSMAText, handles.finOrbitECCText, ...
                                             handles.finOrbitINCText, handles.finOrbitRAANText, handles.finOrbitARGText, ...
                                             [], true, handles.orbitingAboutCombo, celBodyData);
        end

        % Menu selected function: uploadManeuver
        function uploadManeuver_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to uploadManeuver (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            guiData = get(gco,'UserData');
            uploadManeuverToKSP_App(guiData);
        end

        % Callback function: uitoggletool2
        function uitoggletool2_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable rotation based on the
            % tool's current state.
            state = app.uitoggletool2.State;
            rotate3d(app.OptimalTwoBurnOrbitChangeGUI, char(state));
        end

        % Callback function: uitoggletool1
        function uitoggletool1_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool1.State;
            pan(app.OptimalTwoBurnOrbitChangeGUI, char(state));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptimalTwoBurnOrbitChangeGUI = uifigure('Visible', 'off');
            colormap(app.OptimalTwoBurnOrbitChangeGUI, 'parula');
            app.OptimalTwoBurnOrbitChangeGUI.Name = 'Optimal Two Burn Orbit Change';
            app.OptimalTwoBurnOrbitChangeGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.OptimalTwoBurnOrbitChangeGUI.Tag = 'OptimalTwoBurnOrbitChangeGUI';
            app.fileMenu = uimenu(app.OptimalTwoBurnOrbitChangeGUI);
            app.fileMenu.Text = 'File';
            app.fileMenu.Tag = 'fileMenu';
            app.generateReport = uimenu(app.fileMenu);
            app.generateReport.MenuSelectedFcn = createCallbackFcn(app, @generateReport_Callback, true);
            app.generateReport.Text = 'Generate Report';
            app.generateReport.Tag = 'generateReport';
            app.uitoolbar1 = uitoolbar(app.OptimalTwoBurnOrbitChangeGUI);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uitoggletool1 = uitoggletool(app.uitoolbar1);
            app.uitoggletool1.Tag = 'uitoggletool1';
            app.uitoggletool1.Tooltip = 'Pan';
            app.uitoggletool1.ClickedCallback = createCallbackFcn(app, @uitoggletool1_ClickedCallback, true);
            app.uitoggletool1.Icon = 'pan_image.png';
            app.uitoggletool2 = uitoggletool(app.uitoolbar1);
            app.uitoggletool2.Tag = 'uitoggletool2';
            app.uitoggletool2.Tooltip = 'Rotate 3D';
            app.uitoggletool2.ClickedCallback = createCallbackFcn(app, @uitoggletool2_ClickedCallback, true);
            app.uitoggletool2.Icon = '3d_rotate.png';
            app.GridLayout = uigridlayout(app.OptimalTwoBurnOrbitChangeGUI);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.titleText = uilabel(app.GridLayout2);
            app.titleText.Tag = 'titleText';
            app.titleText.Text = {'KSP Trajectory Optimization Tool'; 'Optimal Two Burn Orbit Change'};
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = 'o2boc_20230328.png';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.uipanel2 = uipanel(app.GridLayout4);
            app.uipanel2.Title = 'General Parameters';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout9 = uigridlayout(app.uipanel2);
            app.text1 = uilabel(app.GridLayout9);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Orbiting About';
            app.orbitingAboutCombo = uidropdown(app.GridLayout9);
            app.orbitingAboutCombo.Items = {'Pop-up Menu'};
            app.orbitingAboutCombo.Tag = 'orbitingAboutCombo';
            app.orbitingAboutCombo.Tooltip = 'This is the body about which the Initial Orbit is about.';
            app.orbitingAboutCombo.Value = 'Pop-up Menu';
            app.uipanel3 = uipanel(app.GridLayout4);
            app.uipanel3.Title = 'Initial Orbit Info';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout10 = uigridlayout(app.uipanel3);
            app.text6 = uilabel(app.GridLayout10);
            app.text6.Tag = 'text6';
            app.text6.Text = 'SMA';
            app.text7 = uilabel(app.GridLayout10);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Ecc.';
            app.text8 = uilabel(app.GridLayout10);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Inc.';
            app.text9 = uilabel(app.GridLayout10);
            app.text9.Tag = 'text9';
            app.text9.Text = 'RAAN';
            app.text10 = uilabel(app.GridLayout10);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Arg. Peri.';
            app.iniOrbitSMAText = uieditfield(app.GridLayout10, 'text');
            app.iniOrbitSMAText.ValueChangedFcn = createCallbackFcn(app, @iniOrbitSMAText_Callback, true);
            app.iniOrbitSMAText.Tag = 'iniOrbitSMAText';
            app.iniOrbitSMAText.Tooltip = 'Semi-major axis of initial orbit.';
            app.iniOrbitSMAText.Value = '700';
            app.iniOrbitECCText = uieditfield(app.GridLayout10, 'text');
            app.iniOrbitECCText.ValueChangedFcn = createCallbackFcn(app, @iniOrbitECCText_Callback, true);
            app.iniOrbitECCText.Tag = 'iniOrbitECCText';
            app.iniOrbitECCText.Tooltip = 'Eccentricity of initial orbit.';
            app.iniOrbitECCText.Value = '0.0';
            app.iniOrbitINCText = uieditfield(app.GridLayout10, 'text');
            app.iniOrbitINCText.ValueChangedFcn = createCallbackFcn(app, @iniOrbitINCText_Callback, true);
            app.iniOrbitINCText.Tag = 'iniOrbitINCText';
            app.iniOrbitINCText.Tooltip = 'Inclination of initial orbit.';
            app.iniOrbitINCText.Value = '0.0';
            app.iniOrbitRAANText = uieditfield(app.GridLayout10, 'text');
            app.iniOrbitRAANText.ValueChangedFcn = createCallbackFcn(app, @iniOrbitRAANText_Callback, true);
            app.iniOrbitRAANText.Tag = 'iniOrbitRAANText';
            app.iniOrbitRAANText.Tooltip = 'Right ascension of the ascending node of initial orbit.';
            app.iniOrbitRAANText.Value = '0.0';
            app.iniOrbitARGText = uieditfield(app.GridLayout10, 'text');
            app.iniOrbitARGText.ValueChangedFcn = createCallbackFcn(app, @iniOrbitARGText_Callback, true);
            app.iniOrbitARGText.Tag = 'iniOrbitARGText';
            app.iniOrbitARGText.Tooltip = 'Argument of periapse of initial orbit.';
            app.iniOrbitARGText.Value = '0.0';
            app.text2 = uilabel(app.GridLayout10);
            app.text2.Tag = 'text2';
            app.text2.Text = 'km';
            app.text3 = uilabel(app.GridLayout10);
            app.text3.Tag = 'text3';
            app.text3.Text = 'deg';
            app.text4 = uilabel(app.GridLayout10);
            app.text4.Tag = 'text4';
            app.text4.Text = 'deg';
            app.text5 = uilabel(app.GridLayout10);
            app.text5.Tag = 'text5';
            app.text5.Text = 'deg';
            app.uipanel4 = uipanel(app.GridLayout4);
            app.uipanel4.Title = 'Final (Desired) Orbit Info';
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout12 = uigridlayout(app.uipanel4);
            app.text15 = uilabel(app.GridLayout12);
            app.text15.Tag = 'text15';
            app.text15.Text = 'SMA';
            app.text16 = uilabel(app.GridLayout12);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Ecc.';
            app.text17 = uilabel(app.GridLayout12);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Inc.';
            app.text18 = uilabel(app.GridLayout12);
            app.text18.Tag = 'text18';
            app.text18.Text = 'RAAN';
            app.text19 = uilabel(app.GridLayout12);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Arg. Peri.';
            app.finOrbitSMAText = uieditfield(app.GridLayout12, 'text');
            app.finOrbitSMAText.ValueChangedFcn = createCallbackFcn(app, @finOrbitSMAText_Callback, true);
            app.finOrbitSMAText.Tag = 'finOrbitSMAText';
            app.finOrbitSMAText.Tooltip = 'Semi-major axis of final orbit.';
            app.finOrbitSMAText.Value = '800';
            app.finOrbitECCText = uieditfield(app.GridLayout12, 'text');
            app.finOrbitECCText.ValueChangedFcn = createCallbackFcn(app, @finOrbitECCText_Callback, true);
            app.finOrbitECCText.Tag = 'finOrbitECCText';
            app.finOrbitECCText.Tooltip = 'Eccentricity of final orbit.';
            app.finOrbitECCText.Value = '0.0';
            app.finOrbitINCText = uieditfield(app.GridLayout12, 'text');
            app.finOrbitINCText.ValueChangedFcn = createCallbackFcn(app, @finOrbitINCText_Callback, true);
            app.finOrbitINCText.Tag = 'finOrbitINCText';
            app.finOrbitINCText.Tooltip = 'Inclination of final orbit.';
            app.finOrbitINCText.Value = '0.0';
            app.finOrbitRAANText = uieditfield(app.GridLayout12, 'text');
            app.finOrbitRAANText.ValueChangedFcn = createCallbackFcn(app, @finOrbitRAANText_Callback, true);
            app.finOrbitRAANText.Tag = 'finOrbitRAANText';
            app.finOrbitRAANText.Tooltip = 'Right ascension of the ascending node of final orbit.';
            app.finOrbitRAANText.Value = '0.0';
            app.finOrbitARGText = uieditfield(app.GridLayout12, 'text');
            app.finOrbitARGText.ValueChangedFcn = createCallbackFcn(app, @finOrbitARGText_Callback, true);
            app.finOrbitARGText.Tag = 'finOrbitARGText';
            app.finOrbitARGText.Tooltip = 'Argument of periapse of final orbit.';
            app.finOrbitARGText.Value = '0.0';
            app.text11 = uilabel(app.GridLayout12);
            app.text11.Tag = 'text11';
            app.text11.Text = 'km';
            app.text12 = uilabel(app.GridLayout12);
            app.text12.Tag = 'text12';
            app.text12.Text = 'deg';
            app.text13 = uilabel(app.GridLayout12);
            app.text13.Tag = 'text13';
            app.text13.Text = 'deg';
            app.text14 = uilabel(app.GridLayout12);
            app.text14.Tag = 'text14';
            app.text14.Text = 'deg';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.Panel = uipanel(app.GridLayout5);
            app.GridLayout6 = uigridlayout(app.Panel);
            app.orbitDispAxes = uiaxes(app.GridLayout6);
            app.orbitDispAxes.Tag = 'orbitDispAxes';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.uipanel5 = uipanel(app.GridLayout7);
            app.uipanel5.Title = 'Burn 1 Parameters';
            app.uipanel5.Tag = 'uipanel5';
            app.GridLayout13 = uigridlayout(app.uipanel5);
            app.fixLoc1Checkbox = uicheckbox(app.GridLayout13);
            app.fixLoc1Checkbox.ValueChangedFcn = createCallbackFcn(app, @fixLoc1Checkbox_Callback, true);
            app.fixLoc1Checkbox.Tag = 'fixLoc1Checkbox';
            app.fixLoc1Checkbox.Tooltip = 'Selecting this will fix the location of the first burn to the location you select.  Location is on the initial orbit.';
            app.fixLoc1Checkbox.Text = 'Fix Burn 1 Location';
            app.text31 = uilabel(app.GridLayout13);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Relative to Initial Orbit';
            app.burn1ParamBtnGrp = uibuttongroup(app.GridLayout13);
            app.burn1ParamBtnGrp.Tag = 'burn1ParamBtnGrp';
            app.fixTA1RadioBtn = uiradiobutton(app.burn1ParamBtnGrp);
            app.fixTA1RadioBtn.Tag = 'fixTA1RadioBtn';
            app.fixTA1RadioBtn.Enable = 'off';
            app.fixTA1RadioBtn.Text = 'True Anomaly';
            app.fixTA1RadioBtn.Value = true;
            app.fixTp1RadioBtn = uiradiobutton(app.burn1ParamBtnGrp);
            app.fixTp1RadioBtn.Tag = 'fixTp1RadioBtn';
            app.fixTp1RadioBtn.Enable = 'off';
            app.fixTp1RadioBtn.Text = 'dT Periapse';
            app.fixedTp1Text = uieditfield(app.burn1ParamBtnGrp, 'text');
            app.fixedTp1Text.Tag = 'fixedTp1Text';
            app.fixedTp1Text.Enable = 'off';
            app.fixedTp1Text.Tooltip = 'Fixed time past periapse of the first burn.  May be positive or negative.';
            app.fixedTp1Text.Value = '0.0';
            app.text28 = uilabel(app.burn1ParamBtnGrp);
            app.text28.Tag = 'text28';
            app.text28.Text = 'sec';
            app.uipanel6 = uipanel(app.GridLayout7);
            app.uipanel6.Title = 'Burn 2 Parameters';
            app.uipanel6.Tag = 'uipanel6';
            app.GridLayout14 = uigridlayout(app.uipanel6);
            app.fixLoc2Checkbox = uicheckbox(app.GridLayout14);
            app.fixLoc2Checkbox.ValueChangedFcn = createCallbackFcn(app, @fixLoc2Checkbox_Callback, true);
            app.fixLoc2Checkbox.Tag = 'fixLoc2Checkbox';
            app.fixLoc2Checkbox.Tooltip = 'Selecting this will fix the location of the second burn to the location you select.  Location is on the final orbit.';
            app.fixLoc2Checkbox.Text = 'Fix Burn 2 Location';
            app.text32 = uilabel(app.GridLayout14);
            app.text32.Tag = 'text32';
            app.text32.Text = 'Relative to Final Orbit';
            app.burn2ParamBtnGrp = uibuttongroup(app.GridLayout14);
            app.burn2ParamBtnGrp.Tag = 'burn2ParamBtnGrp';
            app.fixTA2RadioBtn = uiradiobutton(app.burn2ParamBtnGrp);
            app.fixTA2RadioBtn.Tag = 'fixTA2RadioBtn';
            app.fixTA2RadioBtn.Enable = 'off';
            app.fixTA2RadioBtn.Text = 'True Anomaly';
            app.fixTA2RadioBtn.Value = true;
            app.fixTp2RadioBtn = uiradiobutton(app.burn2ParamBtnGrp);
            app.fixTp2RadioBtn.Tag = 'fixTp2RadioBtn';
            app.fixTp2RadioBtn.Enable = 'off';
            app.fixTp2RadioBtn.Text = 'dT Periapse';
            app.fixedTp2Text = uieditfield(app.burn2ParamBtnGrp, 'text');
            app.fixedTp2Text.Tag = 'fixedTp2Text';
            app.fixedTp2Text.Enable = 'off';
            app.fixedTp2Text.Tooltip = 'Fixed time past periapse of the second burn.  May be positive or negative.';
            app.fixedTp2Text.Value = '0.0';
            app.text30 = uilabel(app.burn2ParamBtnGrp);
            app.text30.Tag = 'text30';
            app.text30.Text = 'sec';
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.text23 = uilabel(app.GridLayout8);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Transfer Orbit Information';
            app.xfrOrbitText = uitextarea(app.GridLayout8);
            app.xfrOrbitText.Tag = 'xfrOrbitText';
            app.xfrOrbitText.Value = {'No Orbit Change Maneuver Computed Yet'};
            app.text24 = uilabel(app.GridLayout8);
            app.text24.Tag = 'text24';
            app.text24.Text = 'DV Maneuver 1 Information';
            app.dvMan1InfoText = uitextarea(app.GridLayout8);
            app.dvMan1InfoText.Tag = 'dvMan1InfoText';
            app.dvMan1InfoText.Value = {'No Orbit Change Maneuver Computed Yet'};
            app.text25 = uilabel(app.GridLayout8);
            app.text25.Tag = 'text25';
            app.text25.Text = 'DV Maneuver 2 Information';
            app.dvMan2InfoText = uitextarea(app.GridLayout8);
            app.dvMan2InfoText.Tag = 'dvMan2InfoText';
            app.dvMan2InfoText.Value = {'No Orbit Change Maneuver Computed Yet'};
            app.computeOptimal2BurnManButton = uibutton(app.GridLayout8, 'push');
            app.computeOptimal2BurnManButton.ButtonPushedFcn = createCallbackFcn(app, @computeOptimal2BurnManButton_Callback, true);
            app.computeOptimal2BurnManButton.Tag = 'computeOptimal2BurnManButton';
            app.computeOptimal2BurnManButton.Tooltip = 'Computes the optimal two burn orbit change maneuver.';
            app.computeOptimal2BurnManButton.Text = 'Compute Optimal Two-Burn Orbit Change Maneuver';
            app.iniOrbitContMenu = uicontextmenu(app.OptimalTwoBurnOrbitChangeGUI);
            app.iniOrbitContMenu.Tag = 'iniOrbitContMenu';
            app.getINIOrbitFromSFSFile = uimenu(app.iniOrbitContMenu);
            app.getINIOrbitFromSFSFile.MenuSelectedFcn = createCallbackFcn(app, @getINIOrbitFromSFSFile_Callback, true);
            app.getINIOrbitFromSFSFile.Text = 'Get Orbit From SFS File';
            app.getINIOrbitFromSFSFile.Tag = 'getINIOrbitFromSFSFile';
            app.getOrbitFromKSP = uimenu(app.iniOrbitContMenu);
            app.getOrbitFromKSP.MenuSelectedFcn = createCallbackFcn(app, @getINIOrbitFromKSPTOTConnect_Callback, true);
            app.getOrbitFromKSP.Text = 'Get Orbit From KSP';
            app.getOrbitFromKSP.Tag = 'getOrbitFromKSP';
            app.getOrbitFromKSPActiveVesselMenu = uimenu(app.iniOrbitContMenu);
            app.getOrbitFromKSPActiveVesselMenu.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromKSPActiveVesselMenu_Callback, true);
            app.getOrbitFromKSPActiveVesselMenu.Text = 'Get Orbit From KSP (Active Vessel) ';
            app.getOrbitFromKSPActiveVesselMenu.Tag = 'getOrbitFromKSPActiveVesselMenu';
            app.copyOrbitToClipboardMenu1 = uimenu(app.iniOrbitContMenu);
            app.copyOrbitToClipboardMenu1.MenuSelectedFcn = createCallbackFcn(app, @copyOrbitToClipboardMenu1_Callback, true);
            app.copyOrbitToClipboardMenu1.Text = 'Copy Orbit to Clipboard';
            app.copyOrbitToClipboardMenu1.Tag = 'copyOrbitToClipboardMenu1';
            app.pasteOrbitFromClipboardMenu1 = uimenu(app.iniOrbitContMenu);
            app.pasteOrbitFromClipboardMenu1.MenuSelectedFcn = createCallbackFcn(app, @pasteOrbitFromClipboardMenu1_Callback, true);
            app.pasteOrbitFromClipboardMenu1.Text = 'Paste Orbit From Clipboard';
            app.pasteOrbitFromClipboardMenu1.Tag = 'pasteOrbitFromClipboardMenu1';
            % Assign app.iniOrbitContMenu
            app.uipanel3.ContextMenu = app.iniOrbitContMenu;
            app.text6.ContextMenu = app.iniOrbitContMenu;
            app.text7.ContextMenu = app.iniOrbitContMenu;
            app.text8.ContextMenu = app.iniOrbitContMenu;
            app.text9.ContextMenu = app.iniOrbitContMenu;
            app.text10.ContextMenu = app.iniOrbitContMenu;
            app.iniOrbitSMAText.ContextMenu = app.iniOrbitContMenu;
            app.iniOrbitECCText.ContextMenu = app.iniOrbitContMenu;
            app.iniOrbitINCText.ContextMenu = app.iniOrbitContMenu;
            app.iniOrbitRAANText.ContextMenu = app.iniOrbitContMenu;
            app.iniOrbitARGText.ContextMenu = app.iniOrbitContMenu;
            app.iniOrbitContMenuWithGetDateTime = uicontextmenu(app.OptimalTwoBurnOrbitChangeGUI);
            app.iniOrbitContMenuWithGetDateTime.Tag = 'iniOrbitContMenuWithGetDateTime';
            app.enterUTAsDateTime = uimenu(app.iniOrbitContMenuWithGetDateTime);
            app.enterUTAsDateTime.MenuSelectedFcn = createCallbackFcn(app, @enterUTAsDateTime_Callback, true);
            app.enterUTAsDateTime.Text = 'Enter UT As Date/Time';
            app.enterUTAsDateTime.Tag = 'enterUTAsDateTime';
            app.getINIOrbitFromSFSFile_1 = uimenu(app.iniOrbitContMenuWithGetDateTime);
            app.getINIOrbitFromSFSFile_1.MenuSelectedFcn = createCallbackFcn(app, @getINIOrbitFromSFSFile_Callback, true);
            app.getINIOrbitFromSFSFile_1.Text = 'Get Orbit From SFS File';
            app.getINIOrbitFromSFSFile_1.Tag = 'getINIOrbitFromSFSFile_1';
            app.getOrbitFromKSP_1 = uimenu(app.iniOrbitContMenuWithGetDateTime);
            app.getOrbitFromKSP_1.MenuSelectedFcn = createCallbackFcn(app, @getINIOrbitFromKSPTOTConnect_Callback, true);
            app.getOrbitFromKSP_1.Text = 'Get Orbit From KSP';
            app.getOrbitFromKSP_1.Tag = 'getOrbitFromKSP_1';
            app.getOrbitFromKSPActiveVesselMenu_1 = uimenu(app.iniOrbitContMenuWithGetDateTime);
            app.getOrbitFromKSPActiveVesselMenu_1.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromKSPActiveVesselMenu_Callback, true);
            app.getOrbitFromKSPActiveVesselMenu_1.Checked = 'on';
            app.getOrbitFromKSPActiveVesselMenu_1.Text = 'Get Orbit From KSP (Active Vessel) ';
            app.getOrbitFromKSPActiveVesselMenu_1.Tag = 'getOrbitFromKSPActiveVesselMenu_1';
            app.copyOrbitToClipboardMenu1_1 = uimenu(app.iniOrbitContMenuWithGetDateTime);
            app.copyOrbitToClipboardMenu1_1.MenuSelectedFcn = createCallbackFcn(app, @copyOrbitToClipboardMenu1_Callback, true);
            app.copyOrbitToClipboardMenu1_1.Text = 'Copy Orbit to Clipboard';
            app.copyOrbitToClipboardMenu1_1.Tag = 'copyOrbitToClipboardMenu1_1';
            app.pasteOrbitFromClipboardMenu1_1 = uimenu(app.iniOrbitContMenuWithGetDateTime);
            app.pasteOrbitFromClipboardMenu1_1.MenuSelectedFcn = createCallbackFcn(app, @pasteOrbitFromClipboardMenu1_Callback, true);
            app.pasteOrbitFromClipboardMenu1_1.Text = 'Paste Orbit From Clipboard';
            app.pasteOrbitFromClipboardMenu1_1.Tag = 'pasteOrbitFromClipboardMenu1_1';
            app.finalOrbitContMenu = uicontextmenu(app.OptimalTwoBurnOrbitChangeGUI);
            app.finalOrbitContMenu.Tag = 'finalOrbitContMenu';
            app.getFinalOrbitFromSFSFile = uimenu(app.finalOrbitContMenu);
            app.getFinalOrbitFromSFSFile.MenuSelectedFcn = createCallbackFcn(app, @getFinalOrbitFromKSPActiveVesselMenu_Callback, true);
            app.getFinalOrbitFromSFSFile.Text = 'Get Orbit From SFS File';
            app.getFinalOrbitFromSFSFile.Tag = 'getFinalOrbitFromSFSFile';
            app.getOrbitFromKSP_2 = uimenu(app.finalOrbitContMenu);
            app.getOrbitFromKSP_2.MenuSelectedFcn = createCallbackFcn(app, @getFinalOrbitFromKSPTOTConnect_Callback, true);
            app.getOrbitFromKSP_2.Text = 'Get Orbit From KSP';
            app.getOrbitFromKSP_2.Tag = 'getOrbitFromKSP_2';
            app.getOrbitFromKSPActiveVesselMenu_2 = uimenu(app.finalOrbitContMenu);
            app.getOrbitFromKSPActiveVesselMenu_2.MenuSelectedFcn = createCallbackFcn(app, @getFinalOrbitFromKSPActiveVesselMenu_Callback, true);
            app.getOrbitFromKSPActiveVesselMenu_2.Text = 'Get Orbit From KSP (Active Vessel)';
            app.getOrbitFromKSPActiveVesselMenu_2.Tag = 'getOrbitFromKSPActiveVesselMenu_2';
            app.copyOrbitToClipboardMenu2 = uimenu(app.finalOrbitContMenu);
            app.copyOrbitToClipboardMenu2.MenuSelectedFcn = createCallbackFcn(app, @copyOrbitToClipboardMenu2_Callback, true);
            app.copyOrbitToClipboardMenu2.Text = 'Copy Orbit to Clipboard';
            app.copyOrbitToClipboardMenu2.Tag = 'copyOrbitToClipboardMenu2';
            app.pasteOrbitFromClipboardMenu2 = uimenu(app.finalOrbitContMenu);
            app.pasteOrbitFromClipboardMenu2.MenuSelectedFcn = createCallbackFcn(app, @pasteOrbitFromClipboardMenu2_Callback, true);
            app.pasteOrbitFromClipboardMenu2.Text = 'Paste Orbit From Clipboard';
            app.pasteOrbitFromClipboardMenu2.Tag = 'pasteOrbitFromClipboardMenu2';
            % Assign app.finalOrbitContMenu
            app.uipanel4.ContextMenu = app.finalOrbitContMenu;
            app.text15.ContextMenu = app.finalOrbitContMenu;
            app.text16.ContextMenu = app.finalOrbitContMenu;
            app.text17.ContextMenu = app.finalOrbitContMenu;
            app.text18.ContextMenu = app.finalOrbitContMenu;
            app.text19.ContextMenu = app.finalOrbitContMenu;
            app.finOrbitSMAText.ContextMenu = app.finalOrbitContMenu;
            app.finOrbitECCText.ContextMenu = app.finalOrbitContMenu;
            app.finOrbitINCText.ContextMenu = app.finalOrbitContMenu;
            app.finOrbitRAANText.ContextMenu = app.finalOrbitContMenu;
            app.finOrbitARGText.ContextMenu = app.finalOrbitContMenu;
            app.uploadManeuver2KSP = uicontextmenu(app.OptimalTwoBurnOrbitChangeGUI);
            app.uploadManeuver2KSP.Tag = 'uploadManeuver2KSP';
            app.uploadManeuver = uimenu(app.uploadManeuver2KSP);
            app.uploadManeuver.MenuSelectedFcn = createCallbackFcn(app, @uploadManeuver_Callback, true);
            app.uploadManeuver.Text = 'Upload Maneuver To KSP';
            app.uploadManeuver.Tag = 'uploadManeuver';
            % Assign app.uploadManeuver2KSP
            app.dvMan1InfoText.ContextMenu = app.uploadManeuver2KSP;
            app.dvMan2InfoText.ContextMenu = app.uploadManeuver2KSP;
            % Show the figure after all components are created
            app.OptimalTwoBurnOrbitChangeGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptimalTwoBurnOrbitChange_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OptimalTwoBurnOrbitChangeGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)OptimalTwoBurnOrbitChange_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.OptimalTwoBurnOrbitChangeGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptimalTwoBurnOrbitChangeGUI)
        end
    end
end