classdef computeDepartureGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        computeDepartBurnFig           matlab.ui.Figure
        computeDepartBurn              matlab.ui.control.Button
        iniOrbitPanel                  matlab.ui.container.Panel
        useMAEpochCheckbox             matlab.ui.control.CheckBox
        uipanel4                       matlab.ui.container.Panel
        text29                         matlab.ui.control.Label
        text28                         matlab.ui.control.Label
        eEpoch                         matlab.ui.control.EditField
        eMeanAnom                      matlab.ui.control.EditField
        text27                         matlab.ui.control.Label
        text25                         matlab.ui.control.Label
        text21                         matlab.ui.control.Label
        text20                         matlab.ui.control.Label
        text19                         matlab.ui.control.Label
        text18                         matlab.ui.control.Label
        text17                         matlab.ui.control.Label
        text16                         matlab.ui.control.Label
        text15                         matlab.ui.control.Label
        text14                         matlab.ui.control.Label
        text13                         matlab.ui.control.Label
        eArg                           matlab.ui.control.EditField
        eRAAN                          matlab.ui.control.EditField
        eInc                           matlab.ui.control.EditField
        eEcc                           matlab.ui.control.EditField
        eSMA                           matlab.ui.control.EditField
        uipanel1                       matlab.ui.container.Panel
        dvtypelabel                    matlab.ui.control.Label
        arrivalTimeDateText            matlab.ui.control.Label
        departTimeDateText             matlab.ui.control.Label
        selectDepartArrivePointBtn     matlab.ui.control.Button
        arriveTimeLabel                matlab.ui.control.Label
        departTimeLabel                matlab.ui.control.Label
        text2                          matlab.ui.control.Label
        arrivalTimeText                matlab.ui.control.EditField
        text1                          matlab.ui.control.Label
        departTimeText                 matlab.ui.control.EditField
        enterUTAsDateTimePlusOptimizeDeparture  matlab.ui.container.ContextMenu
        enterUTAsDateTime              matlab.ui.container.Menu
        getUTFromKSP                   matlab.ui.container.Menu
        findOptimalDepartureForInputArrival  matlab.ui.container.Menu
        enterUTAsDateTimePlusOptimizeArrival  matlab.ui.container.ContextMenu
        enterUTAsDateTime_1            matlab.ui.container.Menu
        getUTFromKSP_1                 matlab.ui.container.Menu
        findOptimalArrivalForInputDeparture  matlab.ui.container.Menu
        findEarliestArrivalForDeltaV   matlab.ui.container.Menu
        findEarliestArrivalForDeltaVAdjustDeparture  matlab.ui.container.Menu
        getOrbitFromKSPFile            matlab.ui.container.ContextMenu
        getOrbitFromSFSFile            matlab.ui.container.Menu
        getOrbitFromKSP                matlab.ui.container.Menu
        getOrbitFromKSPActiveVesselMenu  matlab.ui.container.Menu
        copyOrbitToClipboardMenu       matlab.ui.container.Menu
        pasteOrbitFromClipboardMenu    matlab.ui.container.Menu
        meanAnomalyContextMenu         matlab.ui.container.ContextMenu
        enterUTAsDateTime_2            matlab.ui.container.Menu
        getUTFromKSP_2                 matlab.ui.container.Menu
        getOrbitFromSFSFile_1          matlab.ui.container.Menu
        getOrbitFromKSP_1              matlab.ui.container.Menu
        copyOrbitToClipboardMenu_1     matlab.ui.container.Menu
        pasteOrbitFromClipboardMenu_1  matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function computeDepartureGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app); 

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to computeDepartureGUI (see VARARGIN)

            % Choose default command line output for computeDepartureGUI
            handles.output = hObject;
            centerUIFigure(app.computeDepartBurnFig);
            applySelectedThemeToApp(app);

            % Update handles structure
            guidata(hObject, handles);

            %Possible fix for people with display issues.
            %             checkForCharUnitsInGUI(hObject);

            %Process Inputs
            userData = cell(10,10);
            mainGUIFigure = varargin{1};
            userData{1,1} = mainGUIFigure;
            userData{1,2} = varargin{2};

            setappdata(hObject, 'mainGUIFigure', mainGUIFigure);
            
            handles.mainGUIFigure = mainGUIFigure;
            guidata(hObject, handles);

            set(hObject, 'UserData', userData);
            mainGUIUserData = get(mainGUIFigure,'UserData');

            options = mainGUIUserData{1,9};
            deltaVStrName = deltaVTypeEnum(options.quant2opt);
            dvTypeStr =  {'Delta-V To Optimize:', deltaVStrName};
            set(handles.dvtypelabel, 'String', dvTypeStr);

            set(handles.iniOrbitPanel, 'Title', ['Initial Elliptical Orbit Information (', cap1stLetter(mainGUIUserData{2,1}), ')']);
            set(handles.departTimeLabel, 'String', [cap1stLetter(mainGUIUserData{2,1}), ' Departure Time']);
            set(handles.arriveTimeLabel, 'String', [cap1stLetter(mainGUIUserData{2,2}), ' Arrival Time']);

            dcm_obj = datacursormode(mainGUIFigure);
            info_struct = getCursorInfo(dcm_obj);
            if(mainGUIUserData{2,7} >= 0 && mainGUIUserData{2,8} >= 0)
                departUT = mainGUIUserData{2,7};
                arriveUT = mainGUIUserData{2,8};

                set(handles.departTimeText, 'String', num2str(departUT));
                set(handles.arrivalTimeText, 'String', num2str(arriveUT));

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                departTimeText_Callback(app, evt);
                arrivalTimeText_Callback(app, evt);
            elseif(not(isempty(info_struct)))
                dcmPos = info_struct(1).Position;
                xpos = dcmPos(1);
                ypos = dcmPos(2);

                departUT = convertYearDayHrMnSec2Sec(1, xpos+1, 0, 0, 0);
                arriveUT = convertYearDayHrMnSec2Sec(1, ypos+1, 0, 0, 0);

                set(handles.departTimeText, 'String', num2str(departUT));
                set(handles.arrivalTimeText, 'String', num2str(arriveUT));

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                departTimeText_Callback(app, evt);
                arrivalTimeText_Callback(app, evt);
            end
        end

        % Value changed function: arrivalTimeText
        function arrivalTimeText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = app.arrivalTimeText;
            
            % hObject    handle to arrivalTimeText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of arrivalTimeText as text
            %        str2double(get(hObject,'String')) returns contents of arrivalTimeText as a double
            % newInput = get(hObject,'String');
            newInput = hObject.Value;
            newInput = attemptStrEval(newInput);
            inputSec = str2double(newInput);

            if(checkStrIsNumeric(newInput) && inputSec>=0.0)
                [year, day, hour, minute, sec] = convertSec2YearDayHrMnSec(inputSec);
                dateStr = formDateStr(year, day, hour, minute, sec);
                % set(handles.arrivalTimeDateText, 'String', dateStr);
                app.arrivalTimeDateText.Text = dateStr;
                % set(hObject,'String', num2str(inputSec));
                hObject.Value = num2str(inputSec);
            end
        end

        % Button pushed function: computeDepartBurn
        function computeDepartBurn_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to computeDepartBurn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            userData = get(handles.computeDepartBurnFig, 'UserData');
            userDataMain = get(userData{1,1},'UserData');

            departBody = userDataMain{2,1};
            arrivalBody = userDataMain{2,2};

            errMsg = {};

            eEcc = str2double(get(handles.eEcc,'String'));
            enteredStr = get(handles.eEcc,'String');
            numberName = 'Orbit eccentricity';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(eEcc, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(not(isempty(errMsg)))
                msgbox(errMsg,'Errors were found in departure burn calculation inputs','error');
                return;
            end

            departUT = str2double(get(handles.departTimeText,'String'));
            enteredStr = get(handles.departTimeText,'String');
            numberName = 'Departure time';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(departUT, numberName, lb, ub, isInt, errMsg, enteredStr);

            arrivalUT = str2double(get(handles.arrivalTimeText,'String'));
            enteredStr = get(handles.arrivalTimeText,'String');
            numberName = 'Arrival time';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(arrivalUT, numberName, lb, ub, isInt, errMsg, enteredStr);

            eSMA = str2double(get(handles.eSMA,'String'));
            enteredStr = get(handles.eSMA,'String');
            if(eEcc < 1.0)
                lb = 1;
                ub = Inf;
                numberName = 'Eccentric orbit semi-major axis';
            else
                lb = -Inf;
                ub = -1;
                numberName = 'Hyperbolic orbit semi-major axis';
            end
            isInt = false;
            errMsg = validateNumber(eSMA, numberName, lb, ub, isInt, errMsg, enteredStr);

            eInc = str2double(get(handles.eInc,'String'));
            enteredStr = get(handles.eInc,'String');
            numberName = 'Orbit inclination';
            lb = 0;
            ub = 180;
            isInt = false;
            errMsg = validateNumber(eInc, numberName, lb, ub, isInt, errMsg, enteredStr);
            eInc=eInc*pi/180;

            eRAAN = str2double(get(handles.eRAAN,'String'));
            enteredStr = get(handles.eRAAN,'String');
            numberName = 'Orbit RAAN';
            lb = 0;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(eRAAN, numberName, lb, ub, isInt, errMsg, enteredStr);
            eRAAN=eRAAN*pi/180;

            eArg = str2double(get(handles.eArg,'String'));
            enteredStr = get(handles.eArg,'String');
            numberName = 'Orbit argument of periapse';
            lb = 0;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(eArg, numberName, lb, ub, isInt, errMsg, enteredStr);
            eArg=eArg*pi/180;

            eMA = [];
            eEpoch = [];
            if(get(handles.useMAEpochCheckbox, 'Value')==1)
                eMA = str2double(get(handles.eMeanAnom,'String'));
                enteredStr = get(handles.eMeanAnom,'String');
                numberName = 'Orbit Mean Anomaly';
                lb = 0;
                ub = 360;
                isInt = false;
                errMsg = validateNumber(eMA, numberName, lb, ub, isInt, errMsg, enteredStr);
                eMA=eMA*pi/180;

                eEpoch = str2double(get(handles.eEpoch,'String'));
                enteredStr = get(handles.eEpoch,'String');
                numberName = 'Orbit Epoch';
                lb = 0;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(eEpoch, numberName, lb, ub, isInt, errMsg, enteredStr);
            end

            gmuXfr = userDataMain{2,5};
            cbName = userDataMain{2,6};
            options = userDataMain{1,9};

            if(isempty(errMsg))
                try
%                     mainGUIFigure = getappdata(handles.computeDepartBurnFig, 'mainGUIFigure');
                    hUiProgDlg = uiprogressdlg(app.computeDepartBurnFig, "Title","Compute Departure Orbit");
                    hFig = computeDeparture(userDataMain{1,1}, departBody, arrivalBody, departUT, arrivalUT, eSMA, eEcc, eInc, eRAAN, eArg, gmuXfr, cbName, options, eMA, eEpoch, hUiProgDlg);

                    openToolWindows = getappdata(handles.mainGUIFigure,'openToolWindows');
                    openToolWindows = cleanOpenToolWindowsArr(openToolWindows);
                    openToolWindows(end+1) = hFig;
                    setappdata(handles.mainGUIFigure,'openToolWindows',openToolWindows);
                catch ME
                    msgbox(ME.message);
                end
            else
                msgbox(errMsg,'Errors were found in departure burn calculation inputs','error');
            end
        end

        % Menu selected function: copyOrbitToClipboardMenu, 
        % ...and 1 other component
        function copyOrbitToClipboardMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to copyOrbitToClipboardMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            userData = get(handles.computeDepartBurnFig, 'UserData');
            userData = get(userData{1,1},'UserData');
            celBodyData = userData{1,1};

            departBody = userData{2,1};
            bodyID = celBodyData.(lower(departBody)).id;

            copyOrbitToClipboardFromText(handles.eEpoch, handles.eSMA, handles.eEcc, ...
                handles.eInc, handles.eRAAN, handles.eArg, ...
                handles.eMeanAnom, false, bodyID);
        end

        % Value changed function: departTimeText
        function departTimeText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = app.departTimeText;
            
            % hObject    handle to departTimeText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of departTimeText as text
            %        str2double(get(hObject,'String')) returns contents of departTimeText as a double
            % newInput = get(hObject,'String');
            newInput = hObject.Value;
            newInput = attemptStrEval(newInput);
            inputSec = str2double(newInput);

            if(checkStrIsNumeric(newInput) && inputSec>=0.0)
                [year, day, hour, minute, sec] = convertSec2YearDayHrMnSec(inputSec);
                dateStr = formDateStr(year, day, hour, minute, sec);
                % set(handles.departTimeDateText, 'String', dateStr);
                app.departTimeDateText.Text = dateStr;
                % set(hObject,'String', num2str(inputSec));
                hObject.Value = num2str(inputSec);
            end
        end

        % Value changed function: eArg
        function eArg_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to eArg (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of eArg as text
            %        str2double(get(hObject,'String')) returns contents of eArg as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: eEcc
        function eEcc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to eEcc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of eEcc as text
            %        str2double(get(hObject,'String')) returns contents of eEcc as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: eEpoch
        function eEpoch_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to eEpoch (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of eEpoch as text
            %        str2double(get(hObject,'String')) returns contents of eEpoch as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: eInc
        function eInc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to eInc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of eInc as text
            %        str2double(get(hObject,'String')) returns contents of eInc as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: eMeanAnom
        function eMeanAnom_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to eMeanAnom (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of eMeanAnom as text
            %        str2double(get(hObject,'String')) returns contents of eMeanAnom as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: eRAAN
        function eRAAN_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to eRAAN (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of eRAAN as text
            %        str2double(get(hObject,'String')) returns contents of eRAAN as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: eSMA
        function eSMA_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to eSMA (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of eSMA as text
            %        str2double(get(hObject,'String')) returns contents of eSMA as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Menu selected function: enterUTAsDateTime, enterUTAsDateTime_1, 
        % ...and 1 other component
        function enterUTAsDateTime_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to enterUTAsDateTimePlusOptimizeDeparture (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
%             secUT = enterUTAsDateTimeGUI(str2double(get(gco, 'Value')));

            output = AppDesignerGUIOutput({-1});
            enterUTAsDateTimeGUI_App(str2double(get(gco, 'Value')), output);
            secUT = output.output{1};

            if(secUT >= 0)
                set(gco, 'Value', num2str(secUT));
                
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                departTimeText_Callback(app, evt);
                arrivalTimeText_Callback(app, evt);
            end
        end

        % Menu selected function: 
        % findEarliestArrivalForDeltaVAdjustDeparture
        function findEarliestArrivalForDeltaVAdjustDeparture_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to findEarliestArrivalForDeltaVAdjustDeparture (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            userData = get(handles.computeDepartBurnFig, 'UserData');
            userData = get(userData{1,1},'UserData');
            options = userData{1,9};

            departBodyInfo = userData{1,1}.(userData{2,1});
            arrivalBodyInfo = userData{1,1}.(userData{2,2});
            gmu = userData{1,1}.(userData{2,6}).gm;

            departBodyMeanMotion = computeMeanMotion(departBodyInfo.sma, gmu);
            arrivalBodyMeanMotion = computeMeanMotion(arrivalBodyInfo.sma, gmu);
            synPeriod = computeSynodicPeriod(departBodyMeanMotion, arrivalBodyMeanMotion);
            % numSynPeriods = userData{1,9}.porkchopNumSynPeriods;

            errMsg={};

            departUT = str2double(get(handles.departTimeText,'String'));
            enteredStr = get(handles.departTimeText,'String');
            numberName = 'Departure time';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(departUT, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                objFunc = @(x) x(1);
                lb = [departUT+100, departUT];
                ub = [departUT+3*synPeriod departUT+3*synPeriod];

%                 [maxDV, flag] = maxDeltaVInputGUI(options);

                output = AppDesignerGUIOutput({0,-1});
                maxDeltaVInputGUI_App(options, output);
                maxDV = output.output{1};
                flag = output.output{2};

                if(flag == -1)
                    return;
                end

                dvFunc = @(arrivalUT, departUT) findOptimalDepartureArrivalObjFunc(arrivalUT, departUT, departBodyInfo, arrivalBodyInfo, gmu, options.quant2opt, 0);
                nonlconPre = @(arrivalUT, departUT) findEarliestArrivalNonlconFun(arrivalUT, departUT, dvFunc, maxDV);
                nonlcon = @(x) nonlconPre(x(1), x(2));

                dtCenter = lb;
                waitBarStr = 'Searching for earliest arrival time...';

                mainGUIFigure = getappdata(handles.computeDepartBurnFig, 'mainGUIFigure');
                hUiProgDlg = uiprogressdlg(mainGUIFigure);
                
                A= [-1 1];
                b = 0;
                numIter = 75;
                [x, ~, exitflag,output] = multiStartCommonRun(waitBarStr, numIter, objFunc, dtCenter, A, b, lb, ub, nonlcon, hUiProgDlg);
                dv = dvFunc(x(1), x(2));
                arriveUT = x(1);
                departUT = x(2);

                [year, day, hour, minute, sec] = convertSec2YearDayHrMnSec(arriveUT);
                arriveDateStr = formDateStr(year, day, hour, minute, sec);
                [year, day, hour, minute, sec] = convertSec2YearDayHrMnSec(departUT);
                departDateStr = formDateStr(year, day, hour, minute, sec);
                msgBoxStr = {'Solution found!', ...
                    ['New departure date: ', departDateStr], ...
                    ['New arrival date: ', arriveDateStr], ...
                    ['Delta-V: ', num2str(dv), ' km/s']};
                if(exitflag<1)
                    msgBoxStr{end+1} = '';
                    msgBoxStr{end+1} = 'Solver warning message:';
                    msgBoxStr{end+1} = '';
                    msgBoxStr{end+1} = output.message;
                end
                msgbox(msgBoxStr, 'Arrival Date Optimization Complete', 'help');
                
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                
                set(handles.arrivalTimeText, 'String', num2str(arriveUT));
                arrivalTimeText_Callback(app, evt);

                set(handles.departTimeText, 'String', num2str(departUT));
                departTimeText_Callback(app, evt);
            else
                msgbox(errMsg,'Errors were found while starting to compute earliest arrival time','error');
            end
        end

        % Menu selected function: findEarliestArrivalForDeltaV
        function findEarliestArrivalForDeltaV_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to findEarliestArrivalForDeltaV (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            userData = get(handles.computeDepartBurnFig, 'UserData');
            userData = get(userData{1,1},'UserData');
            options = userData{1,9};

            departBodyInfo = userData{1,1}.(userData{2,1});
            arrivalBodyInfo = userData{1,1}.(userData{2,2});
            gmu = userData{1,1}.(userData{2,6}).gm;

            departBodyMeanMotion = computeMeanMotion(departBodyInfo.sma, gmu);
            arrivalBodyMeanMotion = computeMeanMotion(arrivalBodyInfo.sma, gmu);
            synPeriod = computeSynodicPeriod(departBodyMeanMotion, arrivalBodyMeanMotion);
            % numSynPeriods = userData{1,9}.porkchopNumSynPeriods;

            errMsg={};

            departUT = str2double(get(handles.departTimeText,'String'));
            enteredStr = get(handles.departTimeText,'String');
            numberName = 'Departure time';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(departUT, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                objFunc = @(x) x;
                lb = departUT+100;
                ub = Inf;

                output = AppDesignerGUIOutput({0,-1});
                maxDeltaVInputGUI_App(options, output);
                maxDV = output.output{1};
                flag = output.output{2};

                mainGUIFigure = getappdata(handles.computeDepartBurnFig, 'mainGUIFigure');
                hUiProgDlg = uiprogressdlg(mainGUIFigure);
                
                if(flag == -1)
                    return;
                end

                dvFunc = @(arrivalUT, departUT) findOptimalDepartureArrivalObjFunc(arrivalUT, departUT, departBodyInfo, arrivalBodyInfo, gmu, options.quant2opt, 0);
                nonlcon = @(arrivalUT) findEarliestArrivalNonlconFun(arrivalUT, departUT, dvFunc, maxDV);

                dtLB=lb;
                dtUB = lb+10*synPeriod;
                dtCenter = mean([dtLB, dtUB]);
                waitBarStr = 'Searching for earliest arrival time...';

                numIter = 75;
                [arriveUT, ~, exitflag,output] = multiStartCommonRun(waitBarStr, numIter, objFunc, dtCenter, [], [], lb, ub, nonlcon, hUiProgDlg);
                dv = dvFunc(arriveUT, departUT);

                [year, day, hour, minute, sec] = convertSec2YearDayHrMnSec(arriveUT);
                arriveDateStr = formDateStr(year, day, hour, minute, sec);
                msgBoxStr = {'Solution found!', ...
                    ['New arrival date: ', arriveDateStr], ...
                    ['Delta-V: ', num2str(dv), ' km/s']};
                if(exitflag<1)
                    msgBoxStr{end+1} = '';
                    msgBoxStr{end+1} = 'Solver warning message:';
                    msgBoxStr{end+1} = '';
                    msgBoxStr{end+1} = output.message;
                end
                msgbox(msgBoxStr, 'Arrival Date Optimization Complete', 'help');

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                
                set(handles.arrivalTimeText, 'String', num2str(arriveUT));
                arrivalTimeText_Callback(app, evt);
            else
                msgbox(errMsg,'Errors were found while starting to compute earliest arrival time','error');
            end
        end

        % Menu selected function: findOptimalArrivalForInputDeparture
        function findOptimalArrivalForInputDeparture_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to findOptimalArrivalForInputDeparture (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            userData = get(handles.computeDepartBurnFig, 'UserData');
            userData = get(userData{1,1},'UserData');
            options = userData{1,9};

            departBodyInfo = userData{1,1}.(userData{2,1});
            arrivalBodyInfo = userData{1,1}.(userData{2,2});
            gmu = userData{1,1}.(userData{2,6}).gm;

            departBodyMeanMotion = computeMeanMotion(departBodyInfo.sma, gmu);
            arrivalBodyMeanMotion = computeMeanMotion(arrivalBodyInfo.sma, gmu);
            synPeriod = computeSynodicPeriod(departBodyMeanMotion, arrivalBodyMeanMotion);
            % numSynPeriods = userData{1,9}.porkchopNumSynPeriods;

            errMsg={};

            departUT = str2double(get(handles.departTimeText,'String'));
            enteredStr = get(handles.departTimeText,'String');
            numberName = 'Departure time';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(departUT, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                mainGUIFigure = getappdata(handles.computeDepartBurnFig, 'mainGUIFigure');
                hUiProgDlg = uiprogressdlg(mainGUIFigure);
                
                objFunc = @(arrivalTime) findOptimalDepartureArrivalObjFunc(arrivalTime, departUT, departBodyInfo, arrivalBodyInfo, gmu, options.quant2opt, 0);
                atLB = departUT+1000;
                atUB = atLB + (10)*synPeriod;
                atCenter = mean([atLB, atUB]);

                numIter = 75;
                [arriveUT,dv] = multiStartCommonRun('Searching for best arrival time...',...
                    numIter, objFunc, atCenter, [], [], atLB, atUB, [], hUiProgDlg);

                [year, day, hour, minute, sec] = convertSec2YearDayHrMnSec(arriveUT);
                arriveDateStr = formDateStr(year, day, hour, minute, sec);
                msgBoxStr = {'Solution found!', ...
                    ['New arrival date: ', arriveDateStr], ...
                    ['Delta-V: ', num2str(dv), ' km/s']};
                msgbox(msgBoxStr, 'Arrival Date Optimization Complete', 'help');

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                
                set(handles.arrivalTimeText, 'String', num2str(arriveUT));
                arrivalTimeText_Callback(app, evt);
            else
                msgbox(errMsg,'Errors were found while starting to compute best arrival time','error');
            end
        end

        % Menu selected function: findOptimalDepartureForInputArrival
        function findOptimalDepartureForInputArrival_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to findOptimalDepartureForInputArrival (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            userData = get(handles.computeDepartBurnFig, 'UserData');
            userData = get(userData{1,1},'UserData');
            options = userData{1,9};

            departBodyInfo = userData{1,1}.(userData{2,1});
            arrivalBodyInfo = userData{1,1}.(userData{2,2});
            gmu = userData{1,1}.(userData{2,6}).gm;

            departBodyMeanMotion = computeMeanMotion(departBodyInfo.sma, gmu);
            arrivalBodyMeanMotion = computeMeanMotion(arrivalBodyInfo.sma, gmu);
            synPeriod = computeSynodicPeriod(departBodyMeanMotion, arrivalBodyMeanMotion);
            % numSynPeriods = userData{1,9}.porkchopNumSynPeriods;

            errMsg={};

            arrivalUT = str2double(get(handles.arrivalTimeText,'String'));
            enteredStr = get(handles.arrivalTimeText,'String');
            numberName = 'Arrival time';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(arrivalUT, numberName, lb, ub, isInt, errMsg, enteredStr);

            departUT = str2double(get(handles.departTimeText,'String'));
            enteredStr = get(handles.departTimeText,'String');
            numberName = 'Departure time';
            lb = 0;
            ub = arrivalUT;
            isInt = false;
            errMsg = validateNumber(departUT, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                mainGUIFigure = getappdata(handles.computeDepartBurnFig, 'mainGUIFigure');
                hUiProgDlg = uiprogressdlg(mainGUIFigure);
                
                objFunc = @(departUT) findOptimalDepartureArrivalObjFunc(arrivalUT, departUT, departBodyInfo, arrivalBodyInfo, gmu, options.quant2opt, 0);
                dtUB = arrivalUT-100;
                dtLB = max(dtUB - (10)*synPeriod, departUT);
                dtCenter = mean([dtLB, dtUB]);

                numIter = 75;
                [departUT,dv] = multiStartCommonRun('Searching for best departure time...',...
                    numIter, objFunc, dtCenter, [], [], dtLB, dtUB, [], hUiProgDlg);

                [year, day, hour, minute, sec] = convertSec2YearDayHrMnSec(departUT);
                arriveDateStr = formDateStr(year, day, hour, minute, sec);
                msgBoxStr = {'Solution found!', ...
                    ['New departure date: ', arriveDateStr], ...
                    ['Delta-V: ', num2str(dv), ' km/s']};
                msgbox(msgBoxStr, 'Departure Date Optimization Complete', 'help');

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                
                set(handles.departTimeText, 'String', num2str(departUT));
                departTimeText_Callback(app, evt);

            else
                msgbox(errMsg,'Errors were found while starting to compute best arrival time','error');
            end
        end

        % Menu selected function: getOrbitFromKSPActiveVesselMenu
        function getOrbitFromKSPActiveVesselMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getOrbitFromKSPActiveVesselMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            orbitPanelGetOrbitFromKSPTOTConnectActiveVesselCallBack(handles.eSMA, handles.eEcc, handles.eInc, handles.eRAAN, handles.eArg, handles.eMeanAnom, handles.eEpoch);
        end

        % Menu selected function: getOrbitFromKSP, getOrbitFromKSP_1
        function getOrbitFromKSPTOTConnect_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getOrbitFromSFSFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            orbitPanelGetOrbitFromKSPTOTConnectCallBack(handles.eSMA, handles.eEcc, handles.eInc, handles.eRAAN, handles.eArg, handles.eMeanAnom, handles.eEpoch);
        end

        % Menu selected function: getOrbitFromSFSFile, 
        % ...and 1 other component
        function getOrbitFromSFSFile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getOrbitFromSFSFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % userData = get(handles.computeDepartBurnFig, 'UserData');
            % mainGUIHandle = userData{1,1};
            orbitPanelGetOrbitFromSFSContextCallBack(handles.eSMA, handles.eEcc, handles.eInc, handles.eRAAN, handles.eArg, handles.eMeanAnom, handles.eEpoch);
        end

        % Menu selected function: getUTFromKSP, getUTFromKSP_1, 
        % ...and 1 other component
        function getUTFromKSP_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getUTFromKSP (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            secUT = readDoublesFromKSPTOTConnect('GetUT', '', true);
            if(secUT >= 0)
                set(gco, 'Value', num2str(secUT));
                
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                
                departTimeText_Callback(app, evt);
                arrivalTimeText_Callback(app, evt);
            end
        end

        % Menu selected function: pasteOrbitFromClipboardMenu, 
        % ...and 1 other component
        function pasteOrbitFromClipboardMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pasteOrbitFromClipboardMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            pasteOrbitFromClipboard(handles.eEpoch, handles.eSMA, handles.eEcc, ...
                handles.eInc, handles.eRAAN, handles.eArg, ...
                handles.eMeanAnom, false, [], []);
        end

        % Button pushed function: selectDepartArrivePointBtn
        function selectDepartArrivePointBtn_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to selectDepartArrivePointBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
                fig = handles.computeDepartBurnFig;
                userData = get(fig, 'UserData');
                hAxis = userData{1,2};
                axes(hAxis);
            catch ME
                msgbox({'The following error occured while attempting to select the departure/arrival dates:', '', ME.message}, 'Error!', 'error');
                return;
            end

            [departUT,arriveUT] = ginputax(hAxis,1);

            [~, ~, secInDay, ~] = getSecondsInVariousTimeUnits();
            departUTSec = departUT*secInDay;
            arriveUTSec = arriveUT*secInDay;
            set(handles.departTimeText, 'String', num2str(departUTSec));
            set(handles.arrivalTimeText, 'String', num2str(arriveUTSec));
    
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            departTimeText_Callback(app, evt);
            arrivalTimeText_Callback(app, evt);
            figure(handles.computeDepartBurnFig);
        end

        % Value changed function: useMAEpochCheckbox
        function useMAEpochCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to useMAEpochCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of useMAEpochCheckbox
            if(get(hObject,'Value')==1)
                set(handles.eMeanAnom, 'Enable', 'on');
                set(handles.eEpoch, 'Enable', 'on');
            else
                set(handles.eMeanAnom, 'Enable', 'off');
                set(handles.eEpoch, 'Enable', 'off');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.computeDepartBurnFig = uifigure('Visible', 'off');
            colormap(app.computeDepartBurnFig, 'parula');
            app.computeDepartBurnFig.Name = 'Enter Transfer & Initial Orbit Information';
            app.computeDepartBurnFig.Icon = 'logoSquare_48px_transparentBg.png';
            app.computeDepartBurnFig.Tag = 'computeDepartBurnFig';
            app.uipanel1 = uipanel(app.computeDepartBurnFig);
            app.uipanel1.Title = 'Depart/Arrival Information';
            app.uipanel1.Tag = 'uipanel1';
            app.departTimeText = uieditfield(app.uipanel1, 'text');
            app.departTimeText.ValueChangedFcn = createCallbackFcn(app, @departTimeText_Callback, true);
            app.departTimeText.Tag = 'departTimeText';
            app.departTimeText.Tooltip = 'Seconds from game start (see your persistence.sfs file)';
            app.departTimeText.Value = '0.0';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'sec';
            app.arrivalTimeText = uieditfield(app.uipanel1, 'text');
            app.arrivalTimeText.ValueChangedFcn = createCallbackFcn(app, @arrivalTimeText_Callback, true);
            app.arrivalTimeText.Tag = 'arrivalTimeText';
            app.arrivalTimeText.Tooltip = 'Seconds from game start (see your persistence.sfs file)';
            app.arrivalTimeText.Value = '0.0';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'sec';
            app.departTimeLabel = uilabel(app.uipanel1);
            app.departTimeLabel.Tag = 'departTimeLabel';
            app.departTimeLabel.Text = 'Departure Time';
            app.arriveTimeLabel = uilabel(app.uipanel1);
            app.arriveTimeLabel.Tag = 'arriveTimeLabel';
            app.arriveTimeLabel.Text = 'Arrival Time';
            app.selectDepartArrivePointBtn = uibutton(app.uipanel1, 'push');
            app.selectDepartArrivePointBtn.ButtonPushedFcn = createCallbackFcn(app, @selectDepartArrivePointBtn_Callback, true);
            app.selectDepartArrivePointBtn.Tag = 'selectDepartArrivePointBtn';
            app.selectDepartArrivePointBtn.Tooltip = 'Select departure/arrival data on porkchop (click on porkchop plot)';
            app.selectDepartArrivePointBtn.Text = 'Select Departure/Arrival Date';
            app.departTimeDateText = uilabel(app.uipanel1);
            app.departTimeDateText.Tag = 'departTimeDateText';
            app.departTimeDateText.Text = 'Date';
            app.arrivalTimeDateText = uilabel(app.uipanel1);
            app.arrivalTimeDateText.Tag = 'arrivalTimeDateText';
            app.arrivalTimeDateText.Text = 'Date';
            app.dvtypelabel = uilabel(app.uipanel1);
            app.dvtypelabel.Tag = 'dvtypelabel';
            app.dvtypelabel.Text = 'Delta-V Type:';
            app.iniOrbitPanel = uipanel(app.computeDepartBurnFig);
            app.iniOrbitPanel.Title = 'Initial Elliptical Orbit Information (PLANET)';
            app.iniOrbitPanel.Tag = 'iniOrbitPanel';
            app.eSMA = uieditfield(app.iniOrbitPanel, 'text');
            app.eSMA.ValueChangedFcn = createCallbackFcn(app, @eSMA_Callback, true);
            app.eSMA.Tag = 'eSMA';
            app.eSMA.Tooltip = 'Semi-major axis of the initial orbit.';
            app.eSMA.Value = '700.0';
            app.eEcc = uieditfield(app.iniOrbitPanel, 'text');
            app.eEcc.ValueChangedFcn = createCallbackFcn(app, @eEcc_Callback, true);
            app.eEcc.Tag = 'eEcc';
            app.eEcc.Tooltip = 'Eccentricity of the initial orbit.';
            app.eEcc.Value = '0.0';
            app.eInc = uieditfield(app.iniOrbitPanel, 'text');
            app.eInc.ValueChangedFcn = createCallbackFcn(app, @eInc_Callback, true);
            app.eInc.Tag = 'eInc';
            app.eInc.Tooltip = 'Inclination of the initial orbit.';
            app.eInc.Value = '0.0';
            app.eRAAN = uieditfield(app.iniOrbitPanel, 'text');
            app.eRAAN.ValueChangedFcn = createCallbackFcn(app, @eRAAN_Callback, true);
            app.eRAAN.Tag = 'eRAAN';
            app.eRAAN.Tooltip = 'Right ascension of the ascending node of the initial orbit.';
            app.eRAAN.Value = '0.0';
            app.eArg = uieditfield(app.iniOrbitPanel, 'text');
            app.eArg.ValueChangedFcn = createCallbackFcn(app, @eArg_Callback, true);
            app.eArg.Tag = 'eArg';
            app.eArg.Tooltip = 'Argument of periapse of the initial orbit.';
            app.eArg.Value = '0.0';
            app.text13 = uilabel(app.iniOrbitPanel);
            app.text13.Tag = 'text13';
            app.text13.Text = 'km';
            app.text14 = uilabel(app.iniOrbitPanel);
            app.text14.Tag = 'text14';
            app.text14.Text = 'deg';
            app.text15 = uilabel(app.iniOrbitPanel);
            app.text15.Tag = 'text15';
            app.text15.Text = 'deg';
            app.text16 = uilabel(app.iniOrbitPanel);
            app.text16.Tag = 'text16';
            app.text16.Text = 'deg';
            app.text17 = uilabel(app.iniOrbitPanel);
            app.text17.Tag = 'text17';
            app.text17.Text = 'SMA';
            app.text18 = uilabel(app.iniOrbitPanel);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Ecc.';
            app.text19 = uilabel(app.iniOrbitPanel);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Inc.';
            app.text20 = uilabel(app.iniOrbitPanel);
            app.text20.Tag = 'text20';
            app.text20.Text = 'RAAN';
            app.text21 = uilabel(app.iniOrbitPanel);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Arg. Peri.';
            app.uipanel4 = uipanel(app.iniOrbitPanel);
            app.uipanel4.Tag = 'uipanel4';
            app.text25 = uilabel(app.uipanel4);
            app.text25.Tag = 'text25';
            app.text25.Text = 'Mean Anom.';
            app.text27 = uilabel(app.uipanel4);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Epoch';
            app.eMeanAnom = uieditfield(app.uipanel4, 'text');
            app.eMeanAnom.ValueChangedFcn = createCallbackFcn(app, @eMeanAnom_Callback, true);
            app.eMeanAnom.Tag = 'eMeanAnom';
            app.eMeanAnom.Enable = 'off';
            app.eMeanAnom.Tooltip = 'Mean anomaly at the epoch of the initial orbit';
            app.eMeanAnom.Value = '0.0';
            app.eEpoch = uieditfield(app.uipanel4, 'text');
            app.eEpoch.ValueChangedFcn = createCallbackFcn(app, @eEpoch_Callback, true);
            app.eEpoch.Tag = 'eEpoch';
            app.eEpoch.Enable = 'off';
            app.eEpoch.Tooltip = 'Epoch of the orbit.';
            app.eEpoch.Value = '0.0';
            app.text28 = uilabel(app.uipanel4);
            app.text28.Tag = 'text28';
            app.text28.Text = 'deg';
            app.text29 = uilabel(app.uipanel4);
            app.text29.Tag = 'text29';
            app.text29.Text = 'sec';
            app.useMAEpochCheckbox = uicheckbox(app.iniOrbitPanel);
            app.useMAEpochCheckbox.ValueChangedFcn = createCallbackFcn(app, @useMAEpochCheckbox_Callback, true);
            app.useMAEpochCheckbox.Tag = 'useMAEpochCheckbox';
            app.useMAEpochCheckbox.Tooltip = 'Checking this box will have the code use the mean anomaly and epoch provided in the calculations';
            app.useMAEpochCheckbox.Text = 'Use?';
            app.computeDepartBurn = uibutton(app.computeDepartBurnFig, 'push');
            app.computeDepartBurn.ButtonPushedFcn = createCallbackFcn(app, @computeDepartBurn_Callback, true);
            app.computeDepartBurn.Tag = 'computeDepartBurn';
            app.computeDepartBurn.Tooltip = 'Computes the departure burn.';
            app.computeDepartBurn.Text = 'Compute Departure Burn';
            app.enterUTAsDateTimePlusOptimizeDeparture = uicontextmenu(app.computeDepartBurnFig);
            app.enterUTAsDateTimePlusOptimizeDeparture.Tag = 'enterUTAsDateTimePlusOptimizeDeparture';
            app.enterUTAsDateTime = uimenu(app.enterUTAsDateTimePlusOptimizeDeparture);
            app.enterUTAsDateTime.MenuSelectedFcn = createCallbackFcn(app, @enterUTAsDateTime_Callback, true);
            app.enterUTAsDateTime.Text = 'Enter UT As Date/Time';
            app.enterUTAsDateTime.Tag = 'enterUTAsDateTime';
            app.getUTFromKSP = uimenu(app.enterUTAsDateTimePlusOptimizeDeparture);
            app.getUTFromKSP.MenuSelectedFcn = createCallbackFcn(app, @getUTFromKSP_Callback, true);
            app.getUTFromKSP.Text = 'Get UT From KSP';
            app.getUTFromKSP.Tag = 'getUTFromKSP';
            app.findOptimalDepartureForInputArrival = uimenu(app.enterUTAsDateTimePlusOptimizeDeparture);
            app.findOptimalDepartureForInputArrival.MenuSelectedFcn = createCallbackFcn(app, @findOptimalDepartureForInputArrival_Callback, true);
            app.findOptimalDepartureForInputArrival.Text = 'Find Optimal Departure Time For Input Arrival Time';
            app.findOptimalDepartureForInputArrival.Tag = 'findOptimalDepartureForInputArrival';
            % Assign app.enterUTAsDateTimePlusOptimizeDeparture
            app.departTimeText.ContextMenu = app.enterUTAsDateTimePlusOptimizeDeparture;
            app.enterUTAsDateTimePlusOptimizeArrival = uicontextmenu(app.computeDepartBurnFig);
            app.enterUTAsDateTimePlusOptimizeArrival.Tag = 'enterUTAsDateTimePlusOptimizeArrival';
            app.enterUTAsDateTime_1 = uimenu(app.enterUTAsDateTimePlusOptimizeArrival);
            app.enterUTAsDateTime_1.MenuSelectedFcn = createCallbackFcn(app, @enterUTAsDateTime_Callback, true);
            app.enterUTAsDateTime_1.Text = 'Enter UT As Date/Time';
            app.enterUTAsDateTime_1.Tag = 'enterUTAsDateTime_1';
            app.getUTFromKSP_1 = uimenu(app.enterUTAsDateTimePlusOptimizeArrival);
            app.getUTFromKSP_1.MenuSelectedFcn = createCallbackFcn(app, @getUTFromKSP_Callback, true);
            app.getUTFromKSP_1.Text = 'Get UT From KSP';
            app.getUTFromKSP_1.Tag = 'getUTFromKSP_1';
            app.findOptimalArrivalForInputDeparture = uimenu(app.enterUTAsDateTimePlusOptimizeArrival);
            app.findOptimalArrivalForInputDeparture.MenuSelectedFcn = createCallbackFcn(app, @findOptimalArrivalForInputDeparture_Callback, true);
            app.findOptimalArrivalForInputDeparture.Text = 'Find Optimal Arrival Time For Input Departure Time';
            app.findOptimalArrivalForInputDeparture.Tag = 'findOptimalArrivalForInputDeparture';
            app.findEarliestArrivalForDeltaV = uimenu(app.enterUTAsDateTimePlusOptimizeArrival);
            app.findEarliestArrivalForDeltaV.MenuSelectedFcn = createCallbackFcn(app, @findEarliestArrivalForDeltaV_Callback, true);
            app.findEarliestArrivalForDeltaV.Text = 'Find Earliest Arrival for Delta-V';
            app.findEarliestArrivalForDeltaV.Tag = 'findEarliestArrivalForDeltaV';
            app.findEarliestArrivalForDeltaVAdjustDeparture = uimenu(app.enterUTAsDateTimePlusOptimizeArrival);
            app.findEarliestArrivalForDeltaVAdjustDeparture.MenuSelectedFcn = createCallbackFcn(app, @findEarliestArrivalForDeltaVAdjustDeparture_Callback, true);
            app.findEarliestArrivalForDeltaVAdjustDeparture.Text = 'Find Earliest Arrival for Delta-V (Adjust Departure)';
            app.findEarliestArrivalForDeltaVAdjustDeparture.Tag = 'findEarliestArrivalForDeltaVAdjustDeparture';
            % Assign app.enterUTAsDateTimePlusOptimizeArrival
            app.arrivalTimeText.ContextMenu = app.enterUTAsDateTimePlusOptimizeArrival;
            app.getOrbitFromKSPFile = uicontextmenu(app.computeDepartBurnFig);
            app.getOrbitFromKSPFile.Tag = 'getOrbitFromKSPFile';
            app.getOrbitFromSFSFile = uimenu(app.getOrbitFromKSPFile);
            app.getOrbitFromSFSFile.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromSFSFile_Callback, true);
            app.getOrbitFromSFSFile.Text = 'Get Orbit From SFS File';
            app.getOrbitFromSFSFile.Tag = 'getOrbitFromSFSFile';
            app.getOrbitFromKSP = uimenu(app.getOrbitFromKSPFile);
            app.getOrbitFromKSP.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromKSPTOTConnect_Callback, true);
            app.getOrbitFromKSP.Text = 'Get Orbit From KSP';
            app.getOrbitFromKSP.Tag = 'getOrbitFromKSP';
            app.getOrbitFromKSPActiveVesselMenu = uimenu(app.getOrbitFromKSPFile);
            app.getOrbitFromKSPActiveVesselMenu.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromKSPActiveVesselMenu_Callback, true);
            app.getOrbitFromKSPActiveVesselMenu.Text = 'Get Orbit From KSP (Active Vessel)';
            app.getOrbitFromKSPActiveVesselMenu.Tag = 'getOrbitFromKSPActiveVesselMenu';
            app.copyOrbitToClipboardMenu = uimenu(app.getOrbitFromKSPFile);
            app.copyOrbitToClipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @copyOrbitToClipboardMenu_Callback, true);
            app.copyOrbitToClipboardMenu.Text = 'Copy Orbit to Clipboard';
            app.copyOrbitToClipboardMenu.Tag = 'copyOrbitToClipboardMenu';
            app.pasteOrbitFromClipboardMenu = uimenu(app.getOrbitFromKSPFile);
            app.pasteOrbitFromClipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @pasteOrbitFromClipboardMenu_Callback, true);
            app.pasteOrbitFromClipboardMenu.Text = 'Paste Orbit From Clipboard';
            app.pasteOrbitFromClipboardMenu.Tag = 'pasteOrbitFromClipboardMenu';
            % Assign app.getOrbitFromKSPFile
            app.iniOrbitPanel.ContextMenu = app.getOrbitFromKSPFile;
            app.eSMA.ContextMenu = app.getOrbitFromKSPFile;
            app.eEcc.ContextMenu = app.getOrbitFromKSPFile;
            app.eInc.ContextMenu = app.getOrbitFromKSPFile;
            app.eRAAN.ContextMenu = app.getOrbitFromKSPFile;
            app.eArg.ContextMenu = app.getOrbitFromKSPFile;
            app.text17.ContextMenu = app.getOrbitFromKSPFile;
            app.text18.ContextMenu = app.getOrbitFromKSPFile;
            app.text19.ContextMenu = app.getOrbitFromKSPFile;
            app.text20.ContextMenu = app.getOrbitFromKSPFile;
            app.text21.ContextMenu = app.getOrbitFromKSPFile;
            app.text25.ContextMenu = app.getOrbitFromKSPFile;
            app.text27.ContextMenu = app.getOrbitFromKSPFile;
            app.eMeanAnom.ContextMenu = app.getOrbitFromKSPFile;
            app.meanAnomalyContextMenu = uicontextmenu(app.computeDepartBurnFig);
            app.meanAnomalyContextMenu.Tag = 'meanAnomalyContextMenu';
            app.enterUTAsDateTime_2 = uimenu(app.meanAnomalyContextMenu);
            app.enterUTAsDateTime_2.MenuSelectedFcn = createCallbackFcn(app, @enterUTAsDateTime_Callback, true);
            app.enterUTAsDateTime_2.Text = 'Enter UT As Date/Time';
            app.enterUTAsDateTime_2.Tag = 'enterUTAsDateTime_2';
            app.getUTFromKSP_2 = uimenu(app.meanAnomalyContextMenu);
            app.getUTFromKSP_2.MenuSelectedFcn = createCallbackFcn(app, @getUTFromKSP_Callback, true);
            app.getUTFromKSP_2.Text = 'Get UT From KSP';
            app.getUTFromKSP_2.Tag = 'getUTFromKSP_2';
            app.getOrbitFromSFSFile_1 = uimenu(app.meanAnomalyContextMenu);
            app.getOrbitFromSFSFile_1.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromSFSFile_Callback, true);
            app.getOrbitFromSFSFile_1.Text = 'Get Orbit From SFS File';
            app.getOrbitFromSFSFile_1.Tag = 'getOrbitFromSFSFile_1';
            app.getOrbitFromKSP_1 = uimenu(app.meanAnomalyContextMenu);
            app.getOrbitFromKSP_1.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromKSPTOTConnect_Callback, true);
            app.getOrbitFromKSP_1.Text = 'Get Orbit From KSP';
            app.getOrbitFromKSP_1.Tag = 'getOrbitFromKSP_1';
            app.copyOrbitToClipboardMenu_1 = uimenu(app.meanAnomalyContextMenu);
            app.copyOrbitToClipboardMenu_1.MenuSelectedFcn = createCallbackFcn(app, @copyOrbitToClipboardMenu_Callback, true);
            app.copyOrbitToClipboardMenu_1.Text = 'Copy Orbit to Clipboard';
            app.copyOrbitToClipboardMenu_1.Tag = 'copyOrbitToClipboardMenu_1';
            app.pasteOrbitFromClipboardMenu_1 = uimenu(app.meanAnomalyContextMenu);
            app.pasteOrbitFromClipboardMenu_1.MenuSelectedFcn = createCallbackFcn(app, @pasteOrbitFromClipboardMenu_Callback, true);
            app.pasteOrbitFromClipboardMenu_1.Text = 'Paste Orbit From Clipboard';
            app.pasteOrbitFromClipboardMenu_1.Tag = 'pasteOrbitFromClipboardMenu_1';
            % Assign app.meanAnomalyContextMenu
            app.eEpoch.ContextMenu = app.meanAnomalyContextMenu;
            % Show the figure after all components are created
            app.computeDepartBurnFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = computeDepartureGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.computeDepartBurnFig)

                % Execute the startup function
                runStartupFcn(app, @(app)computeDepartureGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.computeDepartBurnFig)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.computeDepartBurnFig)
        end
    end
end