classdef computeSysLvlParams_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        computeSysLvlParamsGUI  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        GridLayout2             matlab.ui.container.GridLayout
        saveExitButton          matlab.ui.control.Button
        GridLayout5             matlab.ui.container.GridLayout
        removeEngineButton      matlab.ui.control.Button
        addEngineButton         matlab.ui.control.Button
        uipanel3                matlab.ui.container.Panel
        GridLayout4             matlab.ui.container.GridLayout
        text17                  matlab.ui.control.Label
        text16                  matlab.ui.control.Label
        avgIspLabel             matlab.ui.control.Label
        totThrustLabel          matlab.ui.control.Label
        text14                  matlab.ui.control.Label
        text13                  matlab.ui.control.Label
        uipanel1                matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        text5                   matlab.ui.control.Label
        text3                   matlab.ui.control.Label
        ispText                 matlab.ui.control.EditField
        thrustText              matlab.ui.control.EditField
        numEnginesText          matlab.ui.control.EditField
        text6                   matlab.ui.control.Label
        text4                   matlab.ui.control.Label
        text2                   matlab.ui.control.Label
        engineSumListbox        matlab.ui.control.ListBox
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function [thrust, isp] = computeSysLvlParamsFromListBox(app, hFig)
            engineParams = get(hFig, 'UserData');
            thrust = 0;
            isp = 0;
            mdot = 0;

            g0 = 9.80665;

            for(i=1:size(engineParams,1)) %#ok<*NO4LP>
                curEntry = engineParams(i,:);

                numEng = curEntry(1);
                curThrust = curEntry(2);
                curIsp = curEntry(3);

                curMdot = curThrust/(g0*curIsp);

                thrust = thrust + numEng*curThrust;
                mdot = mdot + numEng*curMdot;
            end

            if(thrust>0 && mdot>0)
                isp = thrust/(g0*mdot);
            end
        end

        function updateListBoxStr(app, hFig, hEngineSumListbox)
            engineParams = get(hFig, 'UserData');

            listBoxStr = {};
            formDbl = '%9.1f';
            formInt = '%9.0f';

            for(i=1:size(engineParams,1))
                curEntry = engineParams(i,:);

                numEng = curEntry(1);
                curThrust = curEntry(2);
                curIsp = curEntry(3);

                numEngStr = num2str(numEng, formInt);
                curThrustStr = num2str(curThrust/1000, formDbl);
                curIspStr = num2str(curIsp, formDbl);

                curStr = [numEngStr, 'x Engines: ', curThrustStr, ' kN, ', curIspStr, ' sec'];
                listBoxStr{end+1} = curStr; %#ok<AGROW>
            end

            selInd = get(hEngineSumListbox, 'Value');
            if(selInd > size(engineParams, 1))
                set(hEngineSumListbox, 'Value', size(engineParams, 1));
            elseif(selInd < 1)
                set(hEngineSumListbox, 'Value', 1);
            end

            set(hEngineSumListbox, 'String', listBoxStr);
        end

        function saveAndClose(app, handles)
            try
                [thrust, isp] = computeSysLvlParamsFromListBox(app, handles.computeSysLvlParamsGUI);
                engineParams = get(handles.computeSysLvlParamsGUI, 'UserData');

                app.output.output{1} = thrust/1000;
                app.output.output{2} = isp;
                app.output.output{3} = engineParams;

                close(handles.computeSysLvlParamsGUI);
            catch ME %#ok<NASGU>
                app.output.output{1} = [];
                app.output.output{2} = [];
                app.output.output{3} = [];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function computeSysLvlParams_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to computeSysLvlParams (see VARARGIN)

            % Choose default command line output for computeSysLvlParams
            handles.output = hObject;

            % Update handles structure
            guidata(hObject, handles);

            centerUIFigure(app.computeSysLvlParamsGUI);
            applySelectedThemeToApp(app);

            %Possible fix for people with display issues.
%             checkForCharUnitsInGUI(hObject);

            enginesParams = varargin{1};
            set(hObject,'UserData', enginesParams);
            updateListBoxStr(app, hObject, handles.engineSumListbox);

            app.output = varargin{2};

            form = '%9.3f';
            [totThrust, avgIsp] = computeSysLvlParamsFromListBox(app, handles.computeSysLvlParamsGUI);
            set(handles.totThrustLabel,'String',num2str(totThrust/1000, form));
            set(handles.avgIspLabel,'String',num2str(avgIsp, form));

            % UIWAIT makes computeSysLvlParams wait for user response (see UIRESUME)
            uiwait(handles.computeSysLvlParamsGUI);
        end

        % Button pushed function: addEngineButton
        function addEngineButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            %             hObject = handles.addEngineButton;

            % hObject    handle to addEngineButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            engineParams = get(handles.computeSysLvlParamsGUI, 'UserData');

            errMsg = {};

            numEng = str2double(get(handles.numEnginesText, 'String'));
            enteredStr = get(handles.numEnginesText,'String');
            numberName = 'Number of engines';
            lb = 1;
            ub = Inf;
            isInt = true;
            errMsg = validateNumber(numEng, numberName, lb, ub, isInt, errMsg, enteredStr);

            thrust = str2double(get(handles.thrustText, 'String'));
            enteredStr = get(handles.thrustText,'String');
            numberName = 'Thrust';
            lb = 0.001;
            ub = Inf;
            isInt = true;
            errMsg = validateNumber(thrust, numberName, lb, ub, isInt, errMsg, enteredStr);
            thrust = thrust*1000;

            isp = str2double(get(handles.ispText, 'String'));
            enteredStr = get(handles.ispText,'String');
            numberName = 'Thrust';
            lb = 0.001;
            ub = Inf;
            isInt = true;
            errMsg = validateNumber(isp, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))

                engineParams(end+1,:) = [numEng thrust isp];
                set(handles.computeSysLvlParamsGUI, 'UserData', engineParams);

                form = '%9.3f';
                [totThrust, avgIsp] = computeSysLvlParamsFromListBox(app, handles.computeSysLvlParamsGUI);
                set(handles.totThrustLabel,'String',num2str(totThrust/1000, form));
                set(handles.avgIspLabel,'String',num2str(avgIsp, form));

                updateListBoxStr(app, handles.computeSysLvlParamsGUI, handles.engineSumListbox);
            else
                %                 msgbox(errMsg,'Errors were found in orbit change calculation inputs','error');
                uialert(app.computeSysLvlParamsGUI, errMsg, 'Error', "Icon","error");
            end
        end

        % Key press function: computeSysLvlParamsGUI
        function computeSysLvlParamsGUI_KeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to computeSysLvlParamsGUI (see GCBO)
            % eventdata  structure with the following fields (see FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            if(strcmpi(eventdata.Key, 'return') || strcmpi(eventdata.Key, 'enter'))
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                addEngineButton_Callback(app, evt);
            end
        end

        % Value changed function: engineSumListbox
        function engineSumListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to engineSumListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns engineSumListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from engineSumListbox
        end

        % Value changed function: ispText
        function ispText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to ispText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of ispText as text
            %        str2double(get(hObject,'String')) returns contents of ispText as a double
        end

        % Value changed function: numEnginesText
        function numEnginesText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to numEnginesText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of numEnginesText as text
            %        str2double(get(hObject,'String')) returns contents of numEnginesText as a double
        end

        % Button pushed function: removeEngineButton
        function removeEngineButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeEngineButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            selInd = get(handles.engineSumListbox, 'Value');
            if(isempty(selInd) || selInd < 1 || size(selInd,1) > 1 || size(selInd,2) > 1)
                return;
            end

            %     listBoxStr = get(handles.engineSumListbox, 'String');
            %     listBoxStr(selInd) = [];
            %     set(handles.engineSumListbox, 'String', listBoxStr);

            engineParams = get(handles.computeSysLvlParamsGUI, 'UserData');
            if(isempty(engineParams) && selInd==1)
                return;
            end

            engineParams(selInd,:) = [];
            set(handles.computeSysLvlParamsGUI, 'UserData', engineParams);

            form = '%9.3f';
            [totThrust, avgIsp] = computeSysLvlParamsFromListBox(app, handles.computeSysLvlParamsGUI);
            set(handles.totThrustLabel,'String',num2str(totThrust/1000, form));
            set(handles.avgIspLabel,'String',num2str(avgIsp, form));

            updateListBoxStr(app, handles.computeSysLvlParamsGUI, handles.engineSumListbox);
        end

        % Button pushed function: saveExitButton
        function saveExitButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveExitButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
%             uiresume(handles.computeSysLvlParamsGUI);
            app.saveAndClose(handles);
        end

        % Value changed function: thrustText
        function thrustText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to thrustText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of thrustText as text
            %        str2double(get(hObject,'String')) returns contents of thrustText as a double
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.computeSysLvlParamsGUI = uifigure('Visible', 'off');
            colormap(app.computeSysLvlParamsGUI, 'parula');
            app.computeSysLvlParamsGUI.Name = 'Compute System-Level Parameters';
            app.computeSysLvlParamsGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.computeSysLvlParamsGUI.KeyPressFcn = createCallbackFcn(app, @computeSysLvlParamsGUI_KeyPressFcn, true);
            app.computeSysLvlParamsGUI.Tag = 'computeSysLvlParamsGUI';
            app.GridLayout = uigridlayout(app.computeSysLvlParamsGUI);
            app.engineSumListbox = uilistbox(app.GridLayout);
            app.engineSumListbox.Items = {};
            app.engineSumListbox.ValueChangedFcn = createCallbackFcn(app, @engineSumListbox_Callback, true);
            app.engineSumListbox.Tag = 'engineSumListbox';
            app.engineSumListbox.Tooltip = 'Displays all of the engine clusters on the spacecraft and their thrust and specific impulse.';
            app.engineSumListbox.Value = {};
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.uipanel1 = uipanel(app.GridLayout2);
            app.uipanel1.Title = 'Engine Information Input';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.text2 = uilabel(app.GridLayout3);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Num. of Engines';
            app.text4 = uilabel(app.GridLayout3);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Thrust';
            app.text6 = uilabel(app.GridLayout3);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Specific Impulse';
            app.numEnginesText = uieditfield(app.GridLayout3, 'text');
            app.numEnginesText.ValueChangedFcn = createCallbackFcn(app, @numEnginesText_Callback, true);
            app.numEnginesText.Tag = 'numEnginesText';
            app.numEnginesText.Tooltip = 'The number of engines on a spacecraft of a particular cluster or type.';
            app.numEnginesText.Value = '1.0';
            app.thrustText = uieditfield(app.GridLayout3, 'text');
            app.thrustText.ValueChangedFcn = createCallbackFcn(app, @thrustText_Callback, true);
            app.thrustText.Tag = 'thrustText';
            app.thrustText.Tooltip = 'The thrust of ONE engine in the cluster or of the same type.';
            app.thrustText.Value = '50';
            app.ispText = uieditfield(app.GridLayout3, 'text');
            app.ispText.ValueChangedFcn = createCallbackFcn(app, @ispText_Callback, true);
            app.ispText.Tag = 'ispText';
            app.ispText.Tooltip = 'The specific impulse of ONE engine in the cluster or of the same type.';
            app.ispText.Value = '390';
            app.text3 = uilabel(app.GridLayout3);
            app.text3.Tag = 'text3';
            app.text3.Text = 'kN';
            app.text5 = uilabel(app.GridLayout3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'sec';
            app.uipanel3 = uipanel(app.GridLayout2);
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout4 = uigridlayout(app.uipanel3);
            app.text13 = uilabel(app.GridLayout4);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Total Thrust = ';
            app.text14 = uilabel(app.GridLayout4);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Average Isp =';
            app.totThrustLabel = uilabel(app.GridLayout4);
            app.totThrustLabel.Tag = 'totThrustLabel';
            app.totThrustLabel.Tooltip = 'The total thrust of all engines added to the spacecraft.';
            app.totThrustLabel.Text = '0.0';
            app.avgIspLabel = uilabel(app.GridLayout4);
            app.avgIspLabel.Tag = 'avgIspLabel';
            app.avgIspLabel.Tooltip = 'The average specific impulse of all engines added to the spacecraft.';
            app.avgIspLabel.Text = '0.0';
            app.text16 = uilabel(app.GridLayout4);
            app.text16.Tag = 'text16';
            app.text16.Text = 'kN';
            app.text17 = uilabel(app.GridLayout4);
            app.text17.Tag = 'text17';
            app.text17.Text = 'sec';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.addEngineButton = uibutton(app.GridLayout5, 'push');
            app.addEngineButton.ButtonPushedFcn = createCallbackFcn(app, @addEngineButton_Callback, true);
            app.addEngineButton.Tag = 'addEngineButton';
            app.addEngineButton.Tooltip = 'Adds the engine cluster as defined above to the spacecraft.';
            app.addEngineButton.Text = 'Add Engine(s)';
            app.removeEngineButton = uibutton(app.GridLayout5, 'push');
            app.removeEngineButton.ButtonPushedFcn = createCallbackFcn(app, @removeEngineButton_Callback, true);
            app.removeEngineButton.Tag = 'removeEngineButton';
            app.removeEngineButton.Tooltip = 'Removes the selected engine cluster in the listbox from the spacecraft.';
            app.removeEngineButton.Text = 'Remove Engine(s)';
            app.saveExitButton = uibutton(app.GridLayout2, 'push');
            app.saveExitButton.ButtonPushedFcn = createCallbackFcn(app, @saveExitButton_Callback, true);
            app.saveExitButton.Tag = 'saveExitButton';
            app.saveExitButton.Tooltip = 'Saves the result of the calculation and exits.';
            app.saveExitButton.Text = 'Save and Exit';
            % Show the figure after all components are created
            app.computeSysLvlParamsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = computeSysLvlParams_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.computeSysLvlParamsGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)computeSysLvlParams_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.computeSysLvlParamsGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.computeSysLvlParamsGUI)
        end
    end
end