classdef configureCelBodyStateCacheGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConfigureCelestialBodyStateCacheUIFigure  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        CreateCelestialBodyStateCacheButton  matlab.ui.control.Button
        GridLayout2              matlab.ui.container.GridLayout
        GridLayout5              matlab.ui.container.GridLayout
        GridLayout6              matlab.ui.container.GridLayout
        FinalUTEditField         matlab.ui.control.EditField
        FinalUTEditFieldLabel    matlab.ui.control.Label
        text7_2                  matlab.ui.control.Label
        FinalUTDateLabel         matlab.ui.control.Label
        GridLayout3              matlab.ui.container.GridLayout
        GridLayout4              matlab.ui.container.GridLayout
        text7                    matlab.ui.control.Label
        InitialUTEditFieldLabel  matlab.ui.control.Label
        InitialUTEditField       matlab.ui.control.EditField
        InitialUTDateLabel       matlab.ui.control.Label
        text2                    matlab.ui.control.Label
        ContextMenu              matlab.ui.container.ContextMenu
        EnterUTasDateTimeMenu    matlab.ui.container.Menu
    end

    
    properties (Access = private)
        celBodyData % Description
    end
    
    methods (Access = private)
        
        function updateDateLabels(app)  
            inputSec = str2double(app.InitialUTEditField.Value);
            if(checkStrIsNumeric(app.InitialUTEditField.Value))
                [year, day, hour, minute, sec] = convertSec2YearDayHrMnSec(inputSec);
                dateStr = formDateStr(year, day, hour, minute, sec);
                app.InitialUTDateLabel.Text = dateStr;
            end
            
            inputSec = str2double(app.FinalUTEditField.Value);
            if(checkStrIsNumeric(app.FinalUTEditField.Value))
                [year, day, hour, minute, sec] = convertSec2YearDayHrMnSec(inputSec);
                dateStr = formDateStr(year, day, hour, minute, sec);
                app.FinalUTDateLabel.Text = dateStr;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            centerUIFigure(app.ConfigureCelestialBodyStateCacheUIFigure);
            applySelectedThemeToApp(app);
            
            app.celBodyData = varargin{1};
            
            [minUt, maxUt] = app.celBodyData.getStateCacheMinUtMaxUt();
            app.InitialUTEditField.Value = fullAccNum2Str(minUt);
            app.FinalUTEditField.Value = fullAccNum2Str(maxUt);
            
            app.updateDateLabels(); 
        end

        % Value changed function: InitialUTEditField
        function InitialUTEditFieldValueChanged(app, event)
            value = app.InitialUTEditField.Value;

            errMsg = {};

            double = str2double(value);
            enteredStr = value;
            numberName = 'Initial UT';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(double, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                app.InitialUTEditField.Value = fullAccNum2Str(double);
            else
                app.InitialUTEditField.Value = event.PreviousValue;
                uialert(app.ConfigureCelestialBodyStateCacheUIFigure, errMsg, 'Invalid UT Entry', 'Icon','error');
            end
            
            app.updateDateLabels(); 
        end

        % Value changed function: FinalUTEditField
        function FinalUTEditFieldValueChanged(app, event)
            value = app.FinalUTEditField.Value;
            
            errMsg = {};

            double = str2double(value);
            enteredStr = value;
            numberName = 'Initial UT';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(double, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                app.FinalUTEditField.Value = fullAccNum2Str(double);
            else
                app.FinalUTEditField.Value = event.PreviousValue;
                uialert(app.ConfigureCelestialBodyStateCacheUIFigure, errMsg, 'Invalid UT Entry', 'Icon','error');
            end
            
            app.updateDateLabels(); 
        end

        % Menu selected function: EnterUTasDateTimeMenu
        function EnterUTasDateTimeMenuSelected(app, event)
            curObj = app.ConfigureCelestialBodyStateCacheUIFigure.CurrentObject;
            
%             secUT = enterUTAsDateTimeGUI(str2double(curObj.Value));
            
            output = AppDesignerGUIOutput({-1});
            enterUTAsDateTimeGUI_App(str2double(curObj.Value), output);
            secUT = output.output{1};
            
            curObj.Value = fullAccNum2Str(secUT);
            
            app.updateDateLabels(); 
        end

        % Button pushed function: CreateCelestialBodyStateCacheButton
        function CreateCelestialBodyStateCacheButtonPushed(app, event)
            minUT = str2double(app.InitialUTEditField.Value);
            maxUT = str2double(app.FinalUTEditField.Value);

            hWaitbar = uiprogressdlg(app.ConfigureCelestialBodyStateCacheUIFigure, 'Title','Integrating Trajectories', ...
                                    'Message', 'Creating trajectory databases for numerically integrated celestial bodies.  Please wait.', ...
                                    'Indeterminate','on', 'ShowPercentage','on');
            
            drawnow;
            app.celBodyData.updateCelestialBodyStateCache(minUT, maxUT, hWaitbar);
            close(app.ConfigureCelestialBodyStateCacheUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConfigureCelestialBodyStateCacheUIFigure = uifigure('Visible', 'off');
            app.ConfigureCelestialBodyStateCacheUIFigure.Name = 'Configure Celestial Body State Cache';
            app.ConfigureCelestialBodyStateCacheUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout = uigridlayout(app.ConfigureCelestialBodyStateCacheUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Configure Celestial Body State Cache';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.InitialUTDateLabel = uilabel(app.GridLayout3);
            app.InitialUTDateLabel.Tag = 'departDateText';
            app.InitialUTDateLabel.Text = 'Date';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.InitialUTEditField = uieditfield(app.GridLayout4, 'text');
            app.InitialUTEditField.ValueChangedFcn = createCallbackFcn(app, @InitialUTEditFieldValueChanged, true);
            app.InitialUTEditField.Value = '0.0';
            app.InitialUTEditFieldLabel = uilabel(app.GridLayout4);
            app.InitialUTEditFieldLabel.Text = 'Initial UT';
            app.text7 = uilabel(app.GridLayout4);
            app.text7.Tag = 'text7';
            app.text7.Text = 'sec';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.FinalUTDateLabel = uilabel(app.GridLayout5);
            app.FinalUTDateLabel.Tag = 'departDateText';
            app.FinalUTDateLabel.Text = 'Date';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.text7_2 = uilabel(app.GridLayout6);
            app.text7_2.Tag = 'text7';
            app.text7_2.Text = 'sec';
            app.FinalUTEditFieldLabel = uilabel(app.GridLayout6);
            app.FinalUTEditFieldLabel.Text = 'Final UT';
            app.FinalUTEditField = uieditfield(app.GridLayout6, 'text');
            app.FinalUTEditField.ValueChangedFcn = createCallbackFcn(app, @FinalUTEditFieldValueChanged, true);
            app.FinalUTEditField.Value = '0.0';
            app.CreateCelestialBodyStateCacheButton = uibutton(app.GridLayout, 'push');
            app.CreateCelestialBodyStateCacheButton.ButtonPushedFcn = createCallbackFcn(app, @CreateCelestialBodyStateCacheButtonPushed, true);
            app.CreateCelestialBodyStateCacheButton.Text = 'Create Celestial Body State Cache';
            app.ContextMenu = uicontextmenu(app.ConfigureCelestialBodyStateCacheUIFigure);
            app.EnterUTasDateTimeMenu = uimenu(app.ContextMenu);
            app.EnterUTasDateTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @EnterUTasDateTimeMenuSelected, true);
            app.EnterUTasDateTimeMenu.Text = 'Enter UT as Date/Time';
            % Assign app.ContextMenu
            app.InitialUTEditField.ContextMenu = app.ContextMenu;
            app.FinalUTEditField.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.ConfigureCelestialBodyStateCacheUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = configureCelBodyStateCacheGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ConfigureCelestialBodyStateCacheUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ConfigureCelestialBodyStateCacheUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConfigureCelestialBodyStateCacheUIFigure)
        end
    end
end