classdef departDisplayGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        departDispGUI        matlab.ui.Figure
        fileMenu             matlab.ui.container.Menu
        generateReport       matlab.ui.container.Menu
        uitoolbar1           matlab.ui.container.Toolbar
        uitoggletool4        matlab.ui.container.toolbar.ToggleTool
        uitoggletool3        matlab.ui.container.toolbar.ToggleTool
        GridLayout           matlab.ui.container.GridLayout
        GridLayout3          matlab.ui.container.GridLayout
        burnInfoText         matlab.ui.control.TextArea
        text4                matlab.ui.control.Label
        mainOrbitText        matlab.ui.control.TextArea
        transOrbitLabel      matlab.ui.control.Label
        hyperOrbitText       matlab.ui.control.TextArea
        hyperOrbitLabel      matlab.ui.control.Label
        GridLayout2          matlab.ui.container.GridLayout
        DisplayPanel         matlab.ui.container.Panel
        GridLayout4          matlab.ui.container.GridLayout
        departAxis           matlab.ui.control.UIAxes
        plotDisplayPanel     matlab.ui.container.ButtonGroup
        transferOrbitRadio   matlab.ui.control.RadioButton
        departureOrbitRadio  matlab.ui.control.RadioButton
        uploadManeuverMenu   matlab.ui.container.ContextMenu
        uploadManeuver       matlab.ui.container.Menu
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.

            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool4, app.uitoggletool3];
            interactiveTools(event.Source == interactiveTools) = [];

            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');

            % Set figure interactions to 'off'.
            datacursormode(app.departDispGUI, 'off')
            rotate3d(app.departDispGUI, 'off');
            pan(app.departDispGUI, 'off');
            zoom(app.departDispGUI,'off');
        end


        function saveAndClose(app, handles)
            %             varargout{1} = handles.output;

            app.output.output{1} = handles.departAxis;
            app.output.output{2} = handles.hyperOrbitText;
            app.output.output{3} = handles.mainOrbitText;
            app.output.output{4} = handles.burnInfoText;
            app.output.output{5} = handles.departDispGUI;
            app.output.output{6} = handles.hyperOrbitLabel;
            app.output.output{7} = handles.transOrbitLabel;
            app.output.output{8} = handles.departureOrbitRadio;

            app.output.output{9} = handles.output;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function departDisplayGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to departDisplayGUI (see VARARGIN)

            % Choose default command line output for departDisplayGUI
            handles.output = hObject;

            % Update handles structure
            guidata(hObject, handles);

            centerUIFigure(app.departDispGUI);

            app.output = varargin{1};
            app.saveAndClose(handles);
        end

        % Menu selected function: generateReport
        function generateReport_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to generateReport (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [hReport, handlesR] = genericReport(1, 'SINGLE DELTA-V MANEUVER', 'BALLISTIC TRANSFER APPLICATION');

            set(handlesR.rightTextBoxLabel,'String', 'Transfer Orbit Details');

            results = get(handles.departDispGUI,'UserData');
            results = results{1};

            paddLen = 29;
            form = '%9.5f';
            burnInfoTextstr = {};
            burnInfoTextstr{end+1} = [paddStr('Total Delta-V = ',paddLen), num2str(norm(results.burnVect), form), ' km/s'];
            burnInfoTextstr{end+1} = [paddStr('Prograde Delta-V = ',paddLen), num2str(1000*results.burnVect(1), form), ' m/s'];
            burnInfoTextstr{end+1} = [paddStr('Radial Delta-V = ',paddLen), num2str(1000*results.burnVect(3), form), ' m/s'];
            burnInfoTextstr{end+1} = [paddStr('Orbit Normal Delta-V = ',paddLen), num2str(1000*results.burnVect(2), form), ' m/s'];
            burnInfoTextstr{end+1} = '---------------------';
            burnInfoTextstr{end+1} = [paddStr('Departure True Anomaly = ',paddLen), num2str(rad2deg(results.burnTA), form), ' deg'];

            if(isfield(results,'burnTimeBeforePeri'))
                burnInfoTextstr{end+1} = [paddStr('Burn Time Before Peri. = ',paddLen), num2str(results.burnTimeBeforePeri, form), ' sec'];
            end

            if(isfield(results,'burnTimePastPeri'))
                burnInfoTextstr{end+1} = [paddStr('Burn Time Past Peri. = ',paddLen), num2str(results.burnTimeBeforePeri, form), ' sec'];
            end

            if(isfield(results,'iniOrbitPeriod'))
                burnInfoTextstr{end+1} = [paddStr('Ini. Orbit Period = ',paddLen), num2str(results.iniOrbitPeriod, form), ' sec'];
            end
            set(handlesR.leftTextBox, 'String', burnInfoTextstr);

            paddLen = 30;
            xferOrbitText = {};
            xferOrbitText{end+1} = ['Transfer Orbit about ', cap1stLetter(results.xferOrbitCBName)];
            xferOrbitText{end+1} = '-----------------------------------------------';
            xferOrbitText{end+1} = [paddStr('Semi-major Axis = ',paddLen), num2str(results.xferOrbit(1), form), ' km'];
            xferOrbitText{end+1} = [paddStr('Eccentricity = ', paddLen), num2str(results.xferOrbit(2))];
            xferOrbitText{end+1} = [paddStr('Inclination = ',paddLen), num2str(rad2deg(AngleZero2Pi(results.xferOrbit(3))), form), ' deg'];
            xferOrbitText{end+1} = [paddStr('Right Ascension of AN = ',paddLen), num2str(rad2deg(AngleZero2Pi(results.xferOrbit(4))), form), ' deg'];
            xferOrbitText{end+1} = [paddStr('Argument of Periapse = ',paddLen), num2str(rad2deg(AngleZero2Pi(results.xferOrbit(5))), form), ' deg'];
            xferOrbitText{end+1} = [paddStr([cap1stLetter(results.departBodyName), ' Depart True Anomaly = '],paddLen), num2str(rad2deg(AngleZero2Pi(results.xferOrbit(6))), form), ' deg'];
            xferOrbitText{end+1} = [paddStr([cap1stLetter(results.arrivalBodyName), ' Arrive True Anomaly = '],paddLen), num2str(rad2deg(AngleZero2Pi(results.xferOrbit(7))), form), ' deg'];

            set(handlesR.rightTextBox, 'String', xferOrbitText);

            set(handlesR.leftAxis,'UserData',results.axisUserData);
            set(handlesR.rightAxis,'UserData',results.axisUserData);

            plotBodyDepartOrbit(handlesR.leftAxis);
            plotTransferOrbit(handlesR.rightAxis, false);
        end

        % Selection changed function: plotDisplayPanel
        function plotDisplayPanel_SelectionChangeFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to the selected object in plotDisplayPanel
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            % handles    structure with handles and user data (see GUIDATA)
            hPlotBtnGrp = findobj(handles.departDispGUI,'Tag','plotDisplayPanel');
            hTypeBtn = get(hPlotBtnGrp,'SelectedObject');
            plotType = get(hTypeBtn, 'Tag');

            departAxis = handles.departAxis;
            axisUserData = get(departAxis,'UserData');
            reset(departAxis);
            set(departAxis,'UserData',axisUserData);
            hLegend = findobj(handles.departDispGUI,'Type','axes','Tag','legend');
            delete(hLegend);

            h = uiprogressdlg(app.departDispGUI, "Indeterminate","on", "Message",'Plotting, please wait...');            
            try
                switch plotType
                    case 'departureOrbitRadio'
                        plotBodyDepartOrbit(departAxis)
                    case 'transferOrbitRadio'
                        plotTransferOrbit(departAxis)
                end
            catch
                close(h);
            end
            close(h);
        end

        % Menu selected function: uploadManeuver
        function uploadManeuver_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to uploadManeuver (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            guiData = get(handles.burnInfoText,'UserData');
            uploadManeuverToKSP(guiData);
        end

        % Clicked callback: uitoggletool3
        function uitoggletool3_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool3.State;
            pan(app.departDispGUI, char(state));
        end

        % Clicked callback: uitoggletool4
        function uitoggletool4_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable rotation based on the
            % tool's current state.
            state = app.uitoggletool4.State;
            rotate3d(app.departDispGUI, char(state));
        end

        % Close request function: departDispGUI
        function departDispGUICloseRequest(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            app.saveAndClose(handles);

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.departDispGUI = uifigure('Visible', 'off');
            app.departDispGUI.Name = 'PLANET Departure Information';
            app.departDispGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.departDispGUI.CloseRequestFcn = createCallbackFcn(app, @departDispGUICloseRequest, true);
            app.departDispGUI.Tag = 'departDispGUI';
            app.fileMenu = uimenu(app.departDispGUI);
            app.fileMenu.Text = 'File';
            app.fileMenu.Tag = 'fileMenu';
            app.generateReport = uimenu(app.fileMenu);
            app.generateReport.MenuSelectedFcn = createCallbackFcn(app, @generateReport_Callback, true);
            app.generateReport.Text = 'Generate Report';
            app.generateReport.Tag = 'generateReport';
            app.uitoolbar1 = uitoolbar(app.departDispGUI);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uitoggletool4 = uitoggletool(app.uitoolbar1);
            app.uitoggletool4.Tag = 'uitoggletool4';
            app.uitoggletool4.Tooltip = 'Rotate 3D';
            app.uitoggletool4.ClickedCallback = createCallbackFcn(app, @uitoggletool4_ClickedCallback, true);
            app.uitoggletool4.Icon = 'uitoggletool4_image.png';
            app.uitoggletool3 = uitoggletool(app.uitoolbar1);
            app.uitoggletool3.Tag = 'uitoggletool3';
            app.uitoggletool3.Tooltip = 'Pan';
            app.uitoggletool3.ClickedCallback = createCallbackFcn(app, @uitoggletool3_ClickedCallback, true);
            app.uitoggletool3.Icon = 'uitoggletool3_image.png';
            app.GridLayout = uigridlayout(app.departDispGUI);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.plotDisplayPanel = uibuttongroup(app.GridLayout2);
            app.plotDisplayPanel.SelectionChangedFcn = createCallbackFcn(app, @plotDisplayPanel_SelectionChangeFcn, true);
            app.plotDisplayPanel.Title = 'Plot Display';
            app.plotDisplayPanel.Tag = 'plotDisplayPanel';
            app.departureOrbitRadio = uiradiobutton(app.plotDisplayPanel);
            app.departureOrbitRadio.Tag = 'departureOrbitRadio';
            app.departureOrbitRadio.Text = '(PLANET) Departure Orbit';
            app.departureOrbitRadio.Value = true;
            app.transferOrbitRadio = uiradiobutton(app.plotDisplayPanel);
            app.transferOrbitRadio.Tag = 'transferOrbitRadio';
            app.transferOrbitRadio.Text = 'Transfer Orbit';
            app.DisplayPanel = uipanel(app.GridLayout2);
            app.DisplayPanel.Title = 'Display';
            app.GridLayout4 = uigridlayout(app.DisplayPanel);
            app.departAxis = uiaxes(app.GridLayout4);
            app.departAxis.Tag = 'departAxis';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.hyperOrbitLabel = uilabel(app.GridLayout3);
            app.hyperOrbitLabel.Tag = 'hyperOrbitLabel';
            app.hyperOrbitLabel.Text = 'PLANET-Centric Hyperbolic Departure Orbit';
            app.hyperOrbitText = uitextarea(app.GridLayout3);
            app.hyperOrbitText.Tag = 'hyperOrbitText';
            app.hyperOrbitText.Value = {'Hyperbolic Departure Orbit'};
            app.transOrbitLabel = uilabel(app.GridLayout3);
            app.transOrbitLabel.Tag = 'transOrbitLabel';
            app.transOrbitLabel.Text = 'SUN-Centric Transfer Orbit';
            app.mainOrbitText = uitextarea(app.GridLayout3);
            app.mainOrbitText.Tag = 'mainOrbitText';
            app.mainOrbitText.Value = {'Main Transfer Orbit'};
            app.text4 = uilabel(app.GridLayout3);
            app.text4.Tag = 'text4';
            app.text4.Text = 'DV Maneuver Information';
            app.burnInfoText = uitextarea(app.GridLayout3);
            app.burnInfoText.Tag = 'burnInfoText';
            app.burnInfoText.Value = {'Burn Information'};
            app.uploadManeuverMenu = uicontextmenu(app.departDispGUI);
            app.uploadManeuverMenu.Tag = 'uploadManeuverMenu';
            app.uploadManeuver = uimenu(app.uploadManeuverMenu);
            app.uploadManeuver.MenuSelectedFcn = createCallbackFcn(app, @uploadManeuver_Callback, true);
            app.uploadManeuver.Text = 'Upload Maneuver To KSP';
            app.uploadManeuver.Tag = 'uploadManeuver';
            % Assign app.uploadManeuverMenu
            app.burnInfoText.ContextMenu = app.uploadManeuverMenu;
            % Show the figure after all components are created
            app.departDispGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = departDisplayGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.departDispGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)departDisplayGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.departDispGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.departDispGUI)
        end
    end
end