classdef enterUTAsDateTimeGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        enterUTAsDateTimeGUI  matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        GridLayout3           matlab.ui.container.GridLayout
        text12                matlab.ui.control.Label
        second                matlab.ui.control.EditField
        text10                matlab.ui.control.Label
        text11                matlab.ui.control.Label
        minute                matlab.ui.control.EditField
        text9                 matlab.ui.control.Label
        hour                  matlab.ui.control.EditField
        text8                 matlab.ui.control.Label
        day                   matlab.ui.control.EditField
        text7                 matlab.ui.control.Label
        year                  matlab.ui.control.EditField
        text2                 matlab.ui.control.Label
        GridLayout2           matlab.ui.container.GridLayout
        okayButton            matlab.ui.control.Button
        timeWarnStr           matlab.ui.control.Label
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput 
    end
    
    methods (Access = private)
        
        function saveAndClose(app, handles)
            try 
                year = str2double(get(handles.year,'String')); %#ok<ADPROPLC> 
                day = str2double(get(handles.day,'String')); %#ok<ADPROPLC> 
                hour = str2double(get(handles.hour,'String')); %#ok<ADPROPLC> 
                min = str2double(get(handles.minute,'String'));
                sec = str2double(get(handles.second,'String'));
            
                app.output.output{1} = convertYearDayHrMnSec2Sec(year, day, hour, min, sec);
                close(handles.enterUTAsDateTimeGUI);
            catch err %#ok<NASGU> 
                app.output.output{1} = -1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function enterUTAsDateTimeGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to enterUTAsDateTimeGUI (see VARARGIN)
            global options_UseEarthTimeSystem;
            % Choose default command line output for enterUTAsDateTimeGUI
            handles.output = hObject;

            % Update handles structure
            guidata(hObject, handles);
            
            centerUIFigure(app.enterUTAsDateTimeGUI);
            applySelectedThemeToApp(app);

            %Possible fix for people with display issues.
%             checkForCharUnitsInGUI(hObject);

            utTime = varargin{1};
            [year, day, hour, minute, second] = convertSec2YearDayHrMnSec(utTime);
            set(handles.year, 'String', num2str(year));
            set(handles.day, 'String', num2str(day));
            set(handles.hour, 'String', num2str(hour));
            set(handles.minute, 'String', num2str(minute));
            set(handles.second, 'String', num2str(second));
            
            app.output = varargin{2};

            if(options_UseEarthTimeSystem)
                timeWarnStr = 'All times are in Earth units!  (1 year = 365 days, 1 day = 24 hours)';
            else
                timeWarnStr = 'All times are in Kerbin units!  (1 year = 426 days, 1 day = 6 hours)';
            end
            set(handles.timeWarnStr,'String',timeWarnStr);

            % UIWAIT makes enterUTAsDateTimeGUI wait for user response (see UIRESUME)
            uiwait(handles.enterUTAsDateTimeGUI);
        end

        % Value changed function: day
        function day_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to day (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of day as text
            %        str2double(get(hObject,'String')) returns contents of day as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Window key press function: enterUTAsDateTimeGUI
        function enterUTAsDateTimeGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to enterUTAsDateTimeGUI (see GCBO)
            % eventdata  structure with the following fields (see FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            switch(eventdata.Key)
                case 'return'
                    okayButton_Callback(app, evt);
                case 'enter'
                    okayButton_Callback(app, evt);
            end
        end

        % Value changed function: hour
        function hour_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to hour (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of hour as text
            %        str2double(get(hObject,'String')) returns contents of hour as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: minute
        function minute_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to minute (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of minute as text
            %        str2double(get(hObject,'String')) returns contents of minute as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Button pushed function: okayButton
        function okayButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to okayButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = {};

            year = str2double(get(handles.year,'String'));
            enteredStr = get(handles.year,'String');
            numberName = 'Year';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(year, numberName, lb, ub, isInt, errMsg, enteredStr);

            day = str2double(get(handles.day,'String'));
            enteredStr = get(handles.day,'String');
            numberName = 'Day';
            lb = 1;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(day, numberName, lb, ub, isInt, errMsg, enteredStr);

            hour = str2double(get(handles.hour,'String'));
            enteredStr = get(handles.hour,'String');
            numberName = 'Hour';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(hour, numberName, lb, ub, isInt, errMsg, enteredStr);

            min = str2double(get(handles.minute,'String'));
            enteredStr = get(handles.minute,'String');
            numberName = 'Minute';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(min, numberName, lb, ub, isInt, errMsg, enteredStr);

            sec = str2double(get(handles.second,'String'));
            enteredStr = get(handles.second,'String');
            numberName = 'Second';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(sec, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                try
%                     uiresume(handles.enterUTAsDateTimeGUI);
                    app.saveAndClose(handles);
                catch ME
%                     msgbox(ME.message);
                    uialert(app.enterUTAsDateTimeGUI, ME.message, 'Error', "Icon","error");
                end
            else
%                 msgbox(errMsg,'Errors were found in date/time inputs','error');
                uialert(app.enterUTAsDateTimeGUI, errMsg, 'Input Errors', "Icon","error");
            end
        end

        % Value changed function: second
        function second_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to second (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of second as text
            %        str2double(get(hObject,'String')) returns contents of second as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: year
        function year_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to year (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of year as text
            %        str2double(get(hObject,'String')) returns contents of year as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.enterUTAsDateTimeGUI = uifigure('Visible', 'off');
            colormap(app.enterUTAsDateTimeGUI, 'parula');
            app.enterUTAsDateTimeGUI.Name = 'Enter Date/Time';
            app.enterUTAsDateTimeGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.enterUTAsDateTimeGUI.WindowKeyPressFcn = createCallbackFcn(app, @enterUTAsDateTimeGUI_WindowKeyPressFcn, true);
            app.enterUTAsDateTimeGUI.Tag = 'enterUTAsDateTimeGUI';
            app.GridLayout = uigridlayout(app.enterUTAsDateTimeGUI);
            app.timeWarnStr = uilabel(app.GridLayout);
            app.timeWarnStr.Tag = 'timeWarnStr';
            app.timeWarnStr.Text = 'All times are in Earth units!  (1 year = 365 days, 1 day = 24 hours)';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.okayButton = uibutton(app.GridLayout2, 'push');
            app.okayButton.ButtonPushedFcn = createCallbackFcn(app, @okayButton_Callback, true);
            app.okayButton.Tag = 'okayButton';
            app.okayButton.Text = 'OK';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.text2 = uilabel(app.GridLayout3);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Year';
            app.year = uieditfield(app.GridLayout3, 'text');
            app.year.ValueChangedFcn = createCallbackFcn(app, @year_Callback, true);
            app.year.Tag = 'year';
            app.year.Value = '01';
            app.text7 = uilabel(app.GridLayout3);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Day';
            app.day = uieditfield(app.GridLayout3, 'text');
            app.day.ValueChangedFcn = createCallbackFcn(app, @day_Callback, true);
            app.day.Tag = 'day';
            app.day.Value = '01';
            app.text8 = uilabel(app.GridLayout3);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Hour';
            app.hour = uieditfield(app.GridLayout3, 'text');
            app.hour.ValueChangedFcn = createCallbackFcn(app, @hour_Callback, true);
            app.hour.Tag = 'hour';
            app.hour.Value = '00';
            app.text9 = uilabel(app.GridLayout3);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Minute';
            app.minute = uieditfield(app.GridLayout3, 'text');
            app.minute.ValueChangedFcn = createCallbackFcn(app, @minute_Callback, true);
            app.minute.Tag = 'minute';
            app.minute.Value = '00';
            app.text11 = uilabel(app.GridLayout3);
            app.text11.Tag = 'text11';
            app.text11.Text = ':';
            app.text10 = uilabel(app.GridLayout3);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Second';
            app.second = uieditfield(app.GridLayout3, 'text');
            app.second.ValueChangedFcn = createCallbackFcn(app, @second_Callback, true);
            app.second.Tag = 'second';
            app.second.Value = '00';
            app.text12 = uilabel(app.GridLayout3);
            app.text12.Tag = 'text12';
            app.text12.Text = ':';
            % Show the figure after all components are created
            app.enterUTAsDateTimeGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = enterUTAsDateTimeGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.enterUTAsDateTimeGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)enterUTAsDateTimeGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.enterUTAsDateTimeGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.enterUTAsDateTimeGUI)
        end
    end
end