classdef enterUTAsRSSDateTimeGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        enterUTAsRSSDateTimeGUI  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout2              matlab.ui.container.GridLayout
        okayButton               matlab.ui.control.Button
        GridLayout3              matlab.ui.container.GridLayout
        RecognizedFormatsLabel   matlab.ui.control.Label
        RecognizedFormatsList    matlab.ui.control.TextArea
        DateTimeEdit             matlab.ui.control.EditField
        timeWarnStr              matlab.ui.control.Label
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput 
    end
    
    methods (Access = private)
        
        function saveAndClose(app, handles)
            try 
                date = get(handles.DateTimeEdit, 'String');

                app.output.output{1} = convertRSSDateTime2Sec(date);

                close(handles.enterUTAsRSSDateTimeGUI);
            catch err 
                app.output.output{1} = -1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function enterUTAsRSSDateTimeGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to enterUTAsDateTimeGUI (see VARARGIN)
            global options_UseEarthTimeSystem;
            % Choose default command line output for enterUTAsRSSDateTimeGUI
            handles.output = hObject;

            % Update handles structure
            guidata(hObject, handles);
            
            centerUIFigure(app.enterUTAsRSSDateTimeGUI);
            applySelectedThemeToApp(app);

            %Possible fix for people with display issues.
%             checkForCharUnitsInGUI(hObject);

            utTime = varargin{1};

            DT = convertSec2RSSDateTime(utTime);

            set(handles.DateTimeEdit, 'String', string(DT));
            
            app.output = varargin{2};

            if not(options_UseEarthTimeSystem)
                uialert(app.enterUTAsRSSDateTimeGUI, errMsg, '"Use Earth Time System" must be checked in the main windows. Otherwise, bad things will happen...', "Icon","error");
            end

            % UIWAIT makes enterUTAsDateTimeGUI wait for user response (see UIRESUME)
            uiwait(handles.enterUTAsRSSDateTimeGUI);
        end

        % Window key press function: enterUTAsRSSDateTimeGUI
        function enterUTAsRSSDateTimeGUI_WindowKeyPressFcn(app, event)
            % Based on other callbacks (GUIDE-style) to maintain similar code structure
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            switch(eventdata.Key)
                case 'return'
                    okayButton_Callback(app, evt);
                case 'enter'
                    okayButton_Callback(app, evt);
            end
        end

        % Button pushed function: okayButton
        function okayButton_Callback(app, event)
            % Based on other callbacks (GUIDE-style) to maintain similar code structure
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            errMsg = {};

            date = get(handles.DateTimeEdit, 'String');

            % Define formats you want to accept
            fmts = ["dd-MM-uuuu HH:mm:ss", "dd-MM-uuuu HH:mm", "dd/MM/uuuu HH:mm:ss", ...
                    "dd/MM/uuuu HH:mm", "uuuu-MM-dd HH:mm:ss", "uuuu-MM-dd HH:mm", ...
                    "dd-MM-uuuu", "dd/MM/uuuu", "uuuu-MM-dd"];
        
            % Try parsing input string with any of the formats
            parsed = NaT;
            for f = fmts
                try
                    parsed = datetime(date, 'InputFormat', f, 'TimeZone', 'UTC');
                    if ~isnat(parsed)
                        break
                    end
                catch
                    % Try next format
                end
            end
        
            if isnat(parsed)
                errMsg = append('Could not parse date string: ', date);
            end

            if(isempty(errMsg))
                try
                    app.saveAndClose(handles);
                catch ME
                    uialert(app.enterUTAsRSSDateTimeGUI, ME.message, 'Error', "Icon","error");
                end
            else
                uialert(app.enterUTAsRSSDateTimeGUI, errMsg, 'Input Errors', "Icon","error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.enterUTAsRSSDateTimeGUI = uifigure('Visible', 'off');
            colormap(app.enterUTAsRSSDateTimeGUI, 'parula');
            app.enterUTAsRSSDateTimeGUI.Name = 'Enter Date/Time';
            app.enterUTAsRSSDateTimeGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.enterUTAsRSSDateTimeGUI.WindowKeyPressFcn = createCallbackFcn(app, @enterUTAsRSSDateTimeGUI_WindowKeyPressFcn, true);
            app.enterUTAsRSSDateTimeGUI.Tag = 'enterUTAsRSSDateTimeGUI';
            app.GridLayout = uigridlayout(app.enterUTAsRSSDateTimeGUI);
            app.timeWarnStr = uilabel(app.GridLayout);
            app.timeWarnStr.Tag = 'RSSDateTimeInstructions';
            app.timeWarnStr.Text = 'Enter date and time as dd-mm-yyyy (HH:MM:SS optional)';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.DateTimeEdit = uieditfield(app.GridLayout3, 'text');
            app.DateTimeEdit.Tag = 'DateTimeEdit';
            app.DateTimeEdit.Value = '1951-01-01';
            app.RecognizedFormatsList = uitextarea(app.GridLayout3);
            app.RecognizedFormatsList.Editable = 'off';
            app.RecognizedFormatsList.Value = {'dd-MM-uuuu HH:mm:ss'; 'dd-MM-uuuu HH:mm'; 'dd/MM/uuuu HH:mm:ss'; 'dd/MM/uuuu HH:mm'; 'uuuu-MM-dd HH:mm:ss'; 'uuuu-MM-dd HH:mm'; 'dd-MM-uuuu'; 'dd/MM/uuuu'; 'uuuu-MM-dd'};
            app.RecognizedFormatsLabel = uilabel(app.GridLayout3);
            app.RecognizedFormatsLabel.Text = 'Recognized formats (read only)';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.okayButton = uibutton(app.GridLayout2, 'push');
            app.okayButton.ButtonPushedFcn = createCallbackFcn(app, @okayButton_Callback, true);
            app.okayButton.Tag = 'okayButton';
            app.okayButton.Text = 'OK';
            % Show the figure after all components are created
            app.enterUTAsRSSDateTimeGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = enterUTAsRSSDateTimeGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.enterUTAsRSSDateTimeGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)enterUTAsRSSDateTimeGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.enterUTAsRSSDateTimeGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.enterUTAsRSSDateTimeGUI)
        end
    end
end