classdef flyByPorkchopPlotterGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        flyByPorkchopPlotterGUI         matlab.ui.Figure
        uitoolbar1                      matlab.ui.container.Toolbar
        uitoggletool1                   matlab.ui.container.toolbar.ToggleTool
        uitoggletool2                   matlab.ui.container.toolbar.ToggleTool
        uitoggletool4                   matlab.ui.container.toolbar.ToggleTool
        uitoggletool3                   matlab.ui.container.toolbar.ToggleTool
        flightTimeBndsPanel             matlab.ui.container.Panel
        maxNumRevsText                  matlab.ui.control.EditField
        text17                          matlab.ui.control.Label
        text14                          matlab.ui.control.Label
        text13                          matlab.ui.control.Label
        maxFlightTimeText               matlab.ui.control.EditField
        text12                          matlab.ui.control.Label
        text11                          matlab.ui.control.Label
        minFlightTimeText               matlab.ui.control.EditField
        dispAxesSelectSlider            matlab.ui.control.Slider
        computeFlybyPorkchopPlotButton  matlab.ui.control.Button
        uipanel3                        matlab.ui.container.Panel
        numContourLvlsText              matlab.ui.control.EditField
        text16                          matlab.ui.control.Label
        text15                          matlab.ui.control.Label
        maxDvToDisplayCombo             matlab.ui.control.DropDown
        includeArrivalVInfCheckbox      matlab.ui.control.CheckBox
        includeDepartureVInfCheckbox    matlab.ui.control.CheckBox
        recomputePlotOnZoomCheckbox     matlab.ui.control.CheckBox
        numPtsPerAxisText               matlab.ui.control.EditField
        text8                           matlab.ui.control.Label
        uipanel2                        matlab.ui.container.Panel
        deleteWayPointRowButton         matlab.ui.control.Button
        addWayPointRowButton            matlab.ui.control.Button
        waypointTable                   matlab.ui.control.Table
        uipanel1                        matlab.ui.container.Panel
        launchWinCloseLabel             matlab.ui.control.Label
        text6                           matlab.ui.control.Label
        launchWindowCloseText           matlab.ui.control.EditField
        text5                           matlab.ui.control.Label
        launchWinOpenLabel              matlab.ui.control.Label
        text3                           matlab.ui.control.Label
        launchWindowOpenText            matlab.ui.control.EditField
        text2                           matlab.ui.control.Label
        text1                           matlab.ui.control.Label
        centralBodyCombo                matlab.ui.control.DropDown
        displayAxes                     matlab.ui.control.UIAxes
        LWindowContextMenu              matlab.ui.container.ContextMenu
        enterUTAsDateTime               matlab.ui.container.Menu
        dispAxesContextMenu             matlab.ui.container.ContextMenu
        placeMarkerLineAtDepartureUTContextMenu  matlab.ui.container.Menu
        clearMarkerLinesFromPlotContextMenu  matlab.ui.container.Menu
    end


    methods (Access = private)
        function clearMarkerLinesFromMem(~, handles)
            set(handles.placeMarkerLineAtDepartureUTContextMenu,'UserData',[]);
        end

        function clearMarkerLinesFromPlot(~, handles)
            markers = get(handles.placeMarkerLineAtDepartureUTContextMenu,'UserData');

            for(i=1:size(markers,1))
                if(ishandle(markers(i,2)))
                    delete(markers(i,2));
                end
            end
            drawnow;
        end

        function generatePorkChopPlot(app, elem1, elem2, xsol, numVarsPerAxis, dvToPlot, wayPtBodies, cMat, handles, inclDepartDV, inclArriveDV)
            elem1Solns = unique(xsol(:,elem1));
            elem2Solns = unique(xsol(:,elem2));
            DVs = zeros([numVarsPerAxis,numVarsPerAxis]);
            for(i=1:length(elem1Solns))
                for(j=1:length(elem2Solns))
                    elem1Soln = elem1Solns(i);
                    elem2Soln = elem2Solns(j);

                    Inds = find(xsol(:,elem1) == elem1Soln & xsol(:,elem2) == elem2Soln);
                    if(~isempty(Inds))
                        DVs(i,j) = min(dvToPlot(Inds));
                    end
                end
            end
            DVs = DVs';

            wayptFirst = cap1stLetter(wayPtBodies{1}.name);
            waypt1 = cap1stLetter(wayPtBodies{elem2-1}.name);
            waypt2 = cap1stLetter(wayPtBodies{elem2}.name);

            contents = cellstr(get(handles.maxDvToDisplayCombo,'String'));
            percentMax = str2double(strrep(contents{get(handles.maxDvToDisplayCombo,'Value')},'%',''))/100;
            maxDV = max(max(DVs))*percentMax;
            minDV = min(min(DVs));

            numContourLvls = get(handles.numContourLvlsText,'UserData');

            [~, ~, secInDay, ~] = getSecondsInVariousTimeUnits();
            [~,hConf] = contourf(handles.displayAxes,cMat(:,elem1)/secInDay,cMat(:,elem2)/secInDay,DVs,linspace(minDV,maxDV,numContourLvls));
            set(hConf,'UIContextMenu',get(handles.displayAxes,'UIContextMenu'));

            clearMarkerLinesFromPlot(app, handles);

            t = colorbar('peer',handles.displayAxes);
            set(handles.flyByPorkchopPlotterGUI,'Colormap',getFlybyPorkchopPlotCData());
            xlabel(handles.displayAxes,[wayptFirst, ' Departure UT [days]'],'FontWeight','bold');
            ylabel(handles.displayAxes,[waypt1,' -> ',waypt2,' Flight Time [days]'],'FontWeight','bold');
            title(handles.displayAxes,'Multi-Flyby Sequence Delta-V','FontWeight','bold');

            dvTypeIncl = '(Includes: Powered Flyby Delta-V';
            if(inclDepartDV)
                dvTypeIncl = [dvTypeIncl,', Departure V_Inf'];
            end
            if(inclArriveDV)
                dvTypeIncl = [dvTypeIncl, ', Arrival V_Inf'];
            end
            dvTypeIncl = [dvTypeIncl,')'];
            set(get(t,'ylabel'),'interpreter','none');
            set(get(t,'ylabel'),'String', sprintf(['Total Mission Delta-V [km/s]\n',dvTypeIncl]),'FontWeight','bold');

            axes(handles.displayAxes);
            plotMarkerLines(app, handles);

            CLim = get(gca,'CLim');
            CLim(2) = maxDV;
            caxis(CLim);
            set(gca,'CLim',CLim);

            dcm_obj = datacursormode(handles.flyByPorkchopPlotterGUI);
            porkchopUpdate = @(empty, event_obj) flybyPorkchopUpdateFcn(empty, event_obj, cMat(:,elem1), cMat(:,elem2), DVs, inclDepartDV, inclArriveDV);
            set(dcm_obj, 'UpdateFcn', porkchopUpdate, 'DisplayStyle','datatip');
        end

        function childrenNames = getValidWayPtsFromParent(~, celBodyData, hCentralBodyCombo)
            contents = cellstr(get(hCentralBodyCombo,'String'));
            cbName = contents{get(hCentralBodyCombo,'Value')};
            [~, childrenNames] = getChildrenOfParentInfo(celBodyData, cbName);
        end

        function initDispAxes(~, hDispAxesMFMS)
            hAxis = hDispAxesMFMS;
            axes(hAxis);
            cla(hAxis, 'reset');
            grid on;
            colorbar;
        end

        function initFlightTimeBounds(~, handles, celBodyData)
            wayPtBodies = {};
            waypts = get(handles.waypointTable,'Data');
            for(i=1:length(waypts))
                wayPtBodies{end+1} = celBodyData.(lower(waypts{i})); %#ok<AGROW>
            end

            [lb, ub] = getMultiFlybyLBUB(0, 1, wayPtBodies, celBodyData);
            numLegs = length(wayPtBodies)-1;

            lb = lb(2:1+numLegs);
            ub = ub(2:1+numLegs);
            maxRevs = zeros(1,numLegs);
            bnds = [lb',ub', maxRevs'];
            set(handles.flightTimeBndsPanel,'UserData',bnds);
        end

        function initWayPtTable(app, celBodyData, hWayPtTable, hCentralBodyCombo)
            childrenNames = getValidWayPtsFromParent(app, celBodyData, hCentralBodyCombo);
            childrenNames = childrenNames';

            [mem,Inds] = ismember({'Kerbin','Eve','Duna'},childrenNames);
            if(all(mem))
                initData = childrenNames(Inds);
            else
                if(length(childrenNames)>=3)
                    initData = childrenNames(1:3);
                else
                    initData = childrenNames(1:end);
                end
            end

            set(hWayPtTable,'Data',initData);
            set(hWayPtTable,'ColumnFormat',{childrenNames'});
            set(hWayPtTable,'ColumnName',{'Waypoint'});
            set(hWayPtTable,'ColumnWidth',{135});
            set(hWayPtTable,'ColumnEditable',true);

            set(hWayPtTable,'UserData',size(initData));
        end

        function plotMarkerLines(~, handles)
            markers = get(handles.placeMarkerLineAtDepartureUTContextMenu,'UserData');

            ylim = get(handles.displayAxes,'YLim');
            %     axes(handles.displayAxes);
            for(i=1:size(markers,1))
                markerX = markers(i,1);
                if(~ishandle(markers(i,2)))
                    hold(handles.displayAxes,'on');
                    h = plot(handles.displayAxes,[markerX markerX],ylim,'w-','LineWidth',3);
                    markers(i,2) = h;
                end
                hold(handles.displayAxes,'off');
            end
            set(handles.placeMarkerLineAtDepartureUTContextMenu,'UserData',markers);
        end

        function postZoomCallback(app, obj, evd)
            handles = guidata(obj);
            if(get(handles.recomputePlotOnZoomCheckbox,'Value')==1)
                newXLim = get(evd.Axes,'XLim');
                newYLim = get(evd.Axes,'YLim');

                if(newXLim(1)<0)
                    newXLim(1)=0;
                end
                if(newYLim(1)<0)
                    newYLim(1)=0;
                end
                set(evd.Axes,'XLim',newXLim);
                set(evd.Axes,'YLim',newYLim);

                elem2 = get(handles.dispAxesSelectSlider,'Value');

                bnds = get(handles.flightTimeBndsPanel,'UserData');
                [~, ~, secInDay, ~] = getSecondsInVariousTimeUnits();
                bnds(elem2-1,:) = newYLim*secInDay;
                set(handles.flightTimeBndsPanel,'UserData',bnds);

                [~, ~, secInDay, ~] = getSecondsInVariousTimeUnits();
                set(handles.launchWindowOpenText,'String',num2str(newXLim(1)*secInDay));
                set(handles.launchWindowCloseText,'String',num2str(newXLim(2)*secInDay));

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                launchWindowOpenText_Callback(app, evt);
                launchWindowCloseText_Callback(app, evt);

                eventdataWpT.Indices = [elem2-1 1];
                eventdataWpT.Source = app.flyByPorkchopPlotterGUI;
                eventdataWpT.EventName = 'CellSelectionChanged';
                waypointTable_CellSelectionCallback(app, eventdataWpT);
                drawnow;

                computeFlybyPorkchopPlotButton_Callback(app, evt);
            end
        end

        function setCentralBodyCombo(~, celBodyData, hCentralBodyCombo)
            celBodyFields = fieldnames(celBodyData);
            validBodies = {};
            for(i=1:length(celBodyFields)) %#ok<*NO4LP>
                bodyInfo = celBodyData.(celBodyFields{i});
                [children, ~] = getChildrenOfParentInfo(celBodyData, bodyInfo.name);
                if(bodyInfo.canbecentral==1 && length(children)>=3)
                    validBodies{end+1} = bodyInfo.name; %#ok<AGROW>
                end
            end

            set(hCentralBodyCombo, 'String', char(validBodies));
        end

        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.

            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool1, app.uitoggletool2, app.uitoggletool4, app.uitoggletool3];
            interactiveTools(event.Source == interactiveTools) = [];

            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');

            % Set figure interactions to 'off'.
            datacursormode(app.flyByPorkchopPlotterGUI, 'off')
            rotate3d(app.flyByPorkchopPlotterGUI, 'off');
            pan(app.flyByPorkchopPlotterGUI, 'off');
            zoom(app.flyByPorkchopPlotterGUI,'off');
        end

        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)

            % Load data for component configuration
            componentData = load('flyByPorkchopPlotterGUI_App.mat');

            % Set component properties that require runtime configuration
            app.waypointTable.BackgroundColor = [1 1 1;0.9608 0.9608 0.9608];
            app.waypointTable.ColumnFormat = {{'Moho' 'Kerbin' 'Eve' 'Duna'}};
            app.waypointTable.Data = componentData.waypointTable.Data;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function flyByPorkchopPlotterGUI_OpeningFcn(app, celBodyData)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to flyByPorkchopPlotterGUI (see VARARGIN)

            % Choose default command line output for flyByPorkchopPlotterGUI
            handles.output = hObject;
            centerUIFigure(app.flyByPorkchopPlotterGUI);
            applySelectedThemeToApp(app);

            % Update handles structure
            guidata(hObject, handles);

            %Possible fix for people with display issues.
            %             checkForCharUnitsInGUI(hObject);

            % mainGUIHandle = varargin{1};
            % mainGUIUserData = get(mainGUIHandle,'UserData');
            % celBodyData = varargin{1};
            setappdata(hObject,'celBodyData',celBodyData);

            setCentralBodyCombo(app, celBodyData, handles.centralBodyCombo);
            initWayPtTable(app, celBodyData, handles.waypointTable, handles.centralBodyCombo);
            initDispAxes(app, handles.displayAxes);
            initFlightTimeBounds(app, handles, celBodyData);

            eventdataWpT.Indices = [1 1];
            eventdataWpT.Source = app.flyByPorkchopPlotterGUI;
            eventdataWpT.EventName = 'CellSelectionChanged';
            waypointTable_CellSelectionCallback(app, eventdataWpT);

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            launchWindowOpenText_Callback(app, evt);
            launchWindowCloseText_Callback(app, evt);
            numContourLvlsText_Callback(app, evt);

            hZoom = zoom(handles.flyByPorkchopPlotterGUI);
            set(hZoom,'ActionPostCallback',@app.postZoomCallback);

            hPan = pan(handles.flyByPorkchopPlotterGUI);
            set(hPan,'ActionPostCallback',@app.postZoomCallback);
        end

        % Button pushed function: addWayPointRowButton
        function addWayPointRowButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addWayPointRowButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            celBodyData = getappdata(handles.flyByPorkchopPlotterGUI,'celBodyData');

            wayPtData = get(handles.waypointTable,'Data');
            validChildren = get(handles.waypointTable,'ColumnFormat');
            wayPtData{end+1,1} = validChildren{1}{1};
            set(handles.waypointTable,'Data',wayPtData);

            wayPtBodies = {};
            for(i=1:length(wayPtData))
                wayPtBodies{end+1} = celBodyData.(lower(wayPtData{i})); %#ok<AGROW>
            end

            bnds = get(handles.flightTimeBndsPanel,'UserData');
            [lb, ub] = getMultiFlybyLBUB(0, 1, wayPtBodies, celBodyData);
            numLegs = length(wayPtBodies)-1;
            bnds(end+1,1) = lb(numLegs+1);
            bnds(end,2) = ub(numLegs+1);
            bnds(end,3) = 0;
            set(handles.flightTimeBndsPanel,'UserData',bnds);
        end

        % Value changed function: centralBodyCombo
        function centralBodyCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to centralBodyCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns centralBodyCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from centralBodyCombo
            celBodyData = getappdata(handles.flyByPorkchopPlotterGUI,'celBodyData');
            initWayPtTable(app, celBodyData, handles.waypointTable, handles.centralBodyCombo);

            initFlightTimeBounds(app, handles, celBodyData);

            eventdataWpT.Indices = [1 1];
            eventdataWpT.Source = app.flyByPorkchopPlotterGUI;
            eventdataWpT.EventName = 'CellSelectionChanged';
            waypointTable_CellSelectionCallback(app, eventdataWpT);
        end

        % Menu selected function: clearMarkerLinesFromPlotContextMenu
        function clearMarkerLinesFromPlotContextMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to clearMarkerLinesFromPlotContextMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            clearMarkerLinesFromPlot(app, handles);
            clearMarkerLinesFromMem(app, handles);
        end

        % Button pushed function: computeFlybyPorkchopPlotButton
        function computeFlybyPorkchopPlotButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to computeFlybyPorkchopPlotButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            celBodyData = getappdata(handles.flyByPorkchopPlotterGUI,'celBodyData');

            errMsg = {};

            lWindOpen = str2double(get(handles.launchWindowOpenText,'String'));
            enteredStr = get(handles.launchWindowOpenText,'String');
            numberName = 'Launch window open time';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(lWindOpen, numberName, lb, ub, isInt, errMsg, enteredStr);

            lWindClse = str2double(get(handles.launchWindowCloseText,'String'));
            enteredStr = get(handles.launchWindowCloseText,'String');
            numberName = 'Launch window close time';
            lb = lWindOpen+1;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(lWindClse, numberName, lb, ub, isInt, errMsg, enteredStr);

            wayPtBodies = {};
            waypts = get(handles.waypointTable,'Data');
            for(i=1:length(waypts))
                wayPtBodies{end+1} = celBodyData.(lower(waypts{i})); %#ok<AGROW>
            end

            if(length(wayPtBodies)<2)
                errMsg = {'At least three way points are required.'};
            end

            for(i=1:length(wayPtBodies)-1)
                errMsg = checkIfInputBodiesAreSame(wayPtBodies{i}.name, wayPtBodies{i+1}.name, errMsg, ['Waypoint ', num2str(i)], ['Waypoint ', num2str(i+1)]);
            end

            numVarsPerAxis = str2double(get(handles.numPtsPerAxisText,'String'));
            enteredStr = get(handles.numPtsPerAxisText,'String');
            numberName = 'Number of Points per Axis';
            lb = 5;
            ub = Inf;
            isInt = true;
            errMsg = validateNumber(numVarsPerAxis, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(get(handles.includeDepartureVInfCheckbox,'Value')==1)
                considerDVINf = true;
                set(handles.includeDepartureVInfCheckbox,'UserData',1);
            else
                considerDVINf = false;
                set(handles.includeDepartureVInfCheckbox,'UserData',0);
            end

            if(get(handles.includeArrivalVInfCheckbox,'Value')==1)
                considerAVINf = true;
                set(handles.includeArrivalVInfCheckbox,'UserData',1);
            else
                considerAVINf = false;
                set(handles.includeArrivalVInfCheckbox,'UserData',0);
            end

            if(isempty(errMsg))
                hWaitBar = waitbar(0,'Generating Flyby Porkchop Plot.  Please wait...','WindowStyle','modal');

                %         try
                bnds = get(handles.flightTimeBndsPanel,'UserData');
                [lb, ub] = getMultiFlybyLBUB(lWindOpen, lWindClse-lWindOpen, wayPtBodies, celBodyData);
                lb(2:1+length(bnds(:,1))) = bnds(:,1);
                ub(2:1+length(bnds(:,2))) = bnds(:,2);
                maxRevs = bnds(:,3);

                for(i=1:length(maxRevs))
                    tmpNumRevsArrCell = [-maxRevs(i), maxRevs(i)];

                    if(wayPtBodies{i}.id == wayPtBodies{i+1}.id)
                        tmpNumRevsArrCell(tmpNumRevsArrCell==0) = [];

                        if(isempty(tmpNumRevsArrCell))
                            tmpNumRevsArrCell = [-1,1];
                        end
                    end

                    numRevsArr{i} = tmpNumRevsArrCell; %#ok<AGROW>
                    lb(end+1) = 1; %#ok<AGROW>
                    ub(end+1) = length(tmpNumRevsArrCell); %#ok<AGROW>
                end

                C = {};
                cMat = [];
                for(i=1:length(lb))
                    if(abs(ub(i)-lb(i))>1)
                        C{i} = linspace(lb(i),ub(i),numVarsPerAxis)'; %#ok<AGROW>
                        cMat(:,i) = C{i}; %#ok<AGROW>
                    elseif(abs(ub(i)-lb(i))==1)
                        C{i} = linspace(lb(i),ub(i),2)'; %#ok<AGROW>
                    elseif(abs(ub(i)-lb(i))==0)
                        C{i} = lb(i); %#ok<AGROW>
                    else
                        error('error');
                    end
                end

                waitbar(0.25,hWaitBar,'Generating plot grid...');
                xsol = cartesianProduct(C);
                pause(0.25);

                drawnow;
                waitbar(0.50,hWaitBar,'Computing delta-v...');
                fitnessfcn = @(x) multiFlybyObjFunc(x, numRevsArr,wayPtBodies,false,false, eSMA, eEcc, eInc, eRAAN, eArg, celBodyData);
                [~, ~, ~, ~, ~, ~, vInfDepart, vInfArrive, totDV] =  fitnessfcn(xsol);

                dvToPlot = totDV;

                if(considerDVINf)
                    dvToPlot = dvToPlot + sqrt(sum(abs(vInfDepart).^2,1))';
                end

                if(considerAVINf)
                    dvToPlot = dvToPlot + sqrt(sum(abs(vInfArrive).^2,1))';
                end

                elem1 = 1;
                elem2 = get(handles.dispAxesSelectSlider,'Value');

                resultsToSave = {xsol, numVarsPerAxis, dvToPlot, wayPtBodies, cMat};
                set(handles.dispAxesSelectSlider,'Value',elem2,'Min',2,'Max',length(wayPtBodies)+0.01);
                if(length(wayPtBodies)>2)
                    set(handles.dispAxesSelectSlider,'SliderStep',ones(1,2)*(1/(length(wayPtBodies)-2)));
                    set(handles.dispAxesSelectSlider,'Visible','on');
                else
                    set(handles.dispAxesSelectSlider,'Visible','off');
                end


                waitbar(0.75,hWaitBar,'Plotting delta-v contour map across solution space...');
                clearMarkerLinesFromPlot(app, handles);
                clearMarkerLinesFromMem(app, handles);
                generatePorkChopPlot(app, elem1, elem2, xsol, numVarsPerAxis, dvToPlot, wayPtBodies, cMat, handles, considerDVINf, considerAVINf);
                set(handles.dispAxesSelectSlider,'UserData',resultsToSave);
                set(handles.recomputePlotOnZoomCheckbox,'Enable','on');

                if(ishandle(hWaitBar))
                    close(hWaitBar);
                end
                %         catch ME
                %             if(ishandle(hWaitBar))
                %                 close(hWaitBar);
                %             end
                %             if(strcmp(ME.identifier,'MATLAB:nomem'))
                %                 errordlg(sprintf('It looks like the application ran out of available memory.\nTrying lowering the number of points per axis or removing waypoints.'),'Out of Memory');
                %             else
                %                 errordlg(['Error: ',ME.message],'Error');
                %             end
                %         end
            else
                msgbox(errMsg,'Errors were found in multi-flyby manuever sequencer inputs','error');
            end
        end

        % Button pushed function: deleteWayPointRowButton
        function deleteWayPointRowButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to deleteWayPointRowButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            celBodyData = getappdata(handles.flyByPorkchopPlotterGUI,'celBodyData');

            wayPtData = get(handles.waypointTable,'Data');
            numPreDeleteWayPts = length(wayPtData);
            if(size(wayPtData,1) > 2)
                selected = get(handles.waypointTable,'UserData');
                if(isempty(selected))
                    selected = size(wayPtData);
                end
                if(selected<=length(wayPtData))
                    row = selected(1,1);
                    wayPtData(row,:) = [];

                    bnds = get(handles.flightTimeBndsPanel,'UserData');

                    if(row==1)
                        bnds(1,:) = [];
                    elseif(row == numPreDeleteWayPts)
                        bnds(row-1,:) = [];
                    else
                        preBnds = bnds(1:row-2,:);
                        pstBnds = bnds(row+1:end,:);

                        wayPtBodies = {};
                        for(i=1:length(wayPtData))
                            wayPtBodies{end+1} = celBodyData.(lower(wayPtData{i})); %#ok<AGROW>
                        end
                        [lb, ub] = getMultiFlybyLBUB(0, 1, wayPtBodies, celBodyData);

                        newBnds(1) = lb(row);
                        newBnds(2) = ub(row);
                        newBnds(3) = 0;

                        bnds = [preBnds; newBnds; pstBnds];
                    end

                    set(handles.flightTimeBndsPanel,'UserData',bnds);
                    set(handles.waypointTable,'Data',wayPtData);
                end
            end
        end

        % Value changed function: dispAxesSelectSlider
        function dispAxesSelectSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to dispAxesSelectSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            value = get(hObject,'Value');
            value = round(value);
            set(hObject,'Value',value); drawnow;

            elem1 = 1;
            elem2 = get(hObject,'Value');

            pause(0.1);
            hWaitBar = waitbar(0,'Generating Flyby Porkchop Plot.  Please wait...','WindowStyle','modal');

            if(get(handles.includeDepartureVInfCheckbox,'UserData')==1)
                considerDVINf = true;
            else
                considerDVINf = false;
            end

            if(get(handles.includeArrivalVInfCheckbox,'UserData')==1)
                considerAVINf = true;
            else
                considerAVINf = false;
            end

            try
                resultsToSave = get(handles.dispAxesSelectSlider,'UserData');
                if(~isempty(resultsToSave))
                    xsol = resultsToSave{1};
                    numVarsPerAxis = resultsToSave{2};
                    dvToPlot = resultsToSave{3};
                    wayPtBodies = resultsToSave{4};
                    cMat = resultsToSave{5};

                    generatePorkChopPlot(app, elem1, elem2, xsol, numVarsPerAxis, dvToPlot, wayPtBodies, cMat, handles, considerDVINf, considerAVINf);

                    if(ishandle(hWaitBar))
                        close(hWaitBar);
                    end
                else
                    if(ishandle(hWaitBar))
                        close(hWaitBar);
                    end
                end
            catch
                if(ishandle(hWaitBar))
                    close(hWaitBar);
                end
            end
        end

        % Menu selected function: enterUTAsDateTime
        function enterUTAsDateTime_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to enterUTAsDateTime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
%             secUT = enterUTAsDateTimeGUI(str2double(get(gco, 'Value')));
            
            output = AppDesignerGUIOutput({-1});
            enterUTAsDateTimeGUI_App(str2double(get(gco, 'Value')), output);
            secUT = output.output{1};
            
            if(secUT >= 0)
                set(gco, 'Value', num2str(secUT));

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                launchWindowOpenText_Callback(app, evt);
                launchWindowCloseText_Callback(app, evt);
            end
        end

        % Value changed function: launchWindowCloseText
        function launchWindowCloseText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.launchWindowCloseText;

            % hObject    handle to launchWindowCloseText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of launchWindowCloseText as text
            %        str2double(get(hObject,'String')) returns contents of launchWindowCloseText as a double
            newInput = get(hObject,'String');

            newInput = attemptStrEval(newInput);

            inputSec = str2double(newInput);
            if(checkStrIsNumeric(newInput) && inputSec>=0.0)
                [year, day, hour, minute, sec] = convertSec2YearDayHrMnSec(inputSec);
                dateStr = formDateStr(year, day, hour, minute, sec);
                set(handles.launchWinCloseLabel, 'String', dateStr);
                set(hObject,'String', num2str(inputSec));
            end
        end

        % Value changed function: launchWindowOpenText
        function launchWindowOpenText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.launchWindowOpenText;

            % hObject    handle to launchWindowOpenText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of launchWindowOpenText as text
            %        str2double(get(hObject,'String')) returns contents of launchWindowOpenText as a double
            newInput = get(hObject,'String');

            newInput = attemptStrEval(newInput);

            inputSec = str2double(newInput);
            if(checkStrIsNumeric(newInput) && inputSec>=0.0)
                [year, day, hour, minute, sec] = convertSec2YearDayHrMnSec(inputSec);
                dateStr = formDateStr(year, day, hour, minute, sec);
                set(handles.launchWinOpenLabel, 'String', dateStr);
                set(hObject,'String', num2str(inputSec));
            end
        end

        % Value changed function: maxDvToDisplayCombo
        function maxDvToDisplayCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to maxDvToDisplayCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns maxDvToDisplayCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from maxDvToDisplayCombo
            elem1 = 1;
            elem2 = get(handles.dispAxesSelectSlider,'Value');

            hWaitBar = waitbar(0,'Generating Flyby Porkchop Plot.  Please wait...','WindowStyle','modal');

            if(get(handles.includeDepartureVInfCheckbox,'UserData')==1)
                considerDVINf = true;
            else
                considerDVINf = false;
            end

            if(get(handles.includeArrivalVInfCheckbox,'UserData')==1)
                considerAVINf = true;
            else
                considerAVINf = false;
            end

            try
                resultsToSave = get(handles.dispAxesSelectSlider,'UserData');
                if(~isempty(resultsToSave))
                    xsol = resultsToSave{1};
                    numVarsPerAxis = resultsToSave{2};
                    dvToPlot = resultsToSave{3};
                    wayPtBodies = resultsToSave{4};
                    cMat = resultsToSave{5};

                    generatePorkChopPlot(app, elem1, elem2, xsol, numVarsPerAxis, dvToPlot, wayPtBodies, cMat, handles, considerDVINf, considerAVINf);
                    waitbar(1,hWaitBar,'Done!'); pause(0.25);

                    if(ishandle(hWaitBar))
                        close(hWaitBar);
                    end
                else
                    if(ishandle(hWaitBar))
                        close(hWaitBar);
                    end
                end
            catch
                if(ishandle(hWaitBar))
                    close(hWaitBar);
                end
            end
        end

        % Value changed function: maxFlightTimeText
        function maxFlightTimeText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to maxFlightTimeText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of maxFlightTimeText as text
            %        str2double(get(hObject,'String')) returns contents of maxFlightTimeText as a double
            selected = get(handles.waypointTable,'UserData');
            selected = selected(1);
            bnds = get(handles.flightTimeBndsPanel,'UserData');

            celBodyData = getappdata(handles.flyByPorkchopPlotterGUI,'celBodyData');

            wayPtBodies = {};
            waypts = get(handles.waypointTable,'Data');
            for(i=1:length(waypts))
                wayPtBodies{end+1} = celBodyData.(lower(waypts{i})); %#ok<AGROW>
            end

            if(selected~=length(wayPtBodies))
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);

                newInputD = str2double(newInput);
                [~, ~, secInDay, ~] = getSecondsInVariousTimeUnits();
                if(checkStrIsNumeric(newInput) && newInputD>=0.0 && newInputD > bnds(selected,1)/secInDay)
                    bnds(selected,2) = newInputD * secInDay;
                    set(handles.flightTimeBndsPanel,'UserData',bnds);
                else
                    set(hObject,'String', num2str(bnds(selected,2)/secInDay));
                    errordlg('Invalid maximum flight time entered.  Make sure what you entered is a number and that it is greater than the minimum shown.','Invalid Maximum Flight Time');
                end
            end
        end

        % Value changed function: maxNumRevsText
        function maxNumRevsText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to maxNumRevsText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of maxNumRevsText as text
            %        str2double(get(hObject,'String')) returns contents of maxNumRevsText as a double
            selected = get(handles.waypointTable,'UserData');
            selected = selected(1);
            bnds = get(handles.flightTimeBndsPanel,'UserData');

            celBodyData = getappdata(handles.flyByPorkchopPlotterGUI,'celBodyData');

            wayPtBodies = {};
            waypts = get(handles.waypointTable,'Data');
            for(i=1:length(waypts))
                wayPtBodies{end+1} = celBodyData.(lower(waypts{i})); %#ok<AGROW>
            end

            if(selected~=length(wayPtBodies))
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);

                newInputD = str2double(newInput);
                err = validateNumber(newInputD, '', 0, Inf, true, {}, newInput);

                if(checkStrIsNumeric(newInput) && newInputD>=0.0 && isempty(err))
                    bnds(selected,3) = newInputD;
                    set(handles.flightTimeBndsPanel,'UserData',bnds);
                else
                    set(hObject,'String', num2str(bnds(selected,3)));
                    errordlg('Invalid maximum number of revolutions entered.  Make sure what you entered is a positive integer.','Invalid Maximum Revolutions');
                end
            end
        end

        % Value changed function: minFlightTimeText
        function minFlightTimeText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to minFlightTimeText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of minFlightTimeText as text
            %        str2double(get(hObject,'String')) returns contents of minFlightTimeText as a double
            selected = get(handles.waypointTable,'UserData');
            selected = selected(1);
            bnds = get(handles.flightTimeBndsPanel,'UserData');

            celBodyData = getappdata(handles.flyByPorkchopPlotterGUI,'celBodyData');

            wayPtBodies = {};
            waypts = get(handles.waypointTable,'Data');
            for(i=1:length(waypts))
                wayPtBodies{end+1} = celBodyData.(lower(waypts{i})); %#ok<AGROW>
            end

            if(selected~=length(wayPtBodies))
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);

                newInputD = str2double(newInput);
                [~, ~, secInDay, ~] = getSecondsInVariousTimeUnits();
                if(checkStrIsNumeric(newInput) && newInputD>=0.0 && newInputD < bnds(selected,2)/secInDay)
                    bnds(selected,1) = newInputD * secInDay;
                    set(handles.flightTimeBndsPanel,'UserData',bnds);
                else
                    set(hObject,'String', num2str(bnds(selected,1)/secInDay));
                    errordlg('Invalid minimum flight time entered.  Make sure what you entered is a number and that it is less than the maximum shown.','Invalid Minimum Flight Time');
                end
            end
        end

        % Value changed function: numContourLvlsText
        function numContourLvlsText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.numContourLvlsText;

            % hObject    handle to numContourLvlsText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of numContourLvlsText as text
            %        str2double(get(hObject,'String')) returns contents of numContourLvlsText as a double
            errMsg = {};

            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            numContourLvls = str2double(get(handles.numContourLvlsText,'String'));
            enteredStr = get(handles.numContourLvlsText,'String');
            numberName = 'Number of Contour Levels';
            lb = 5;
            ub = 100;
            isInt = true;
            errMsg = validateNumber(numContourLvls, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                set(handles.numContourLvlsText,'UserData',numContourLvls);
            else
                numContourLvls = get(handles.numContourLvlsText,'UserData');
                if(isempty(numContourLvls))
                    numContourLvls = 20;
                end
                set(handles.numContourLvlsText,'String',num2str(numContourLvls));

                msgbox(errMsg,'Num. Contour Level Error','error');
            end
        end

        % Value changed function: numPtsPerAxisText
        function numPtsPerAxisText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to numPtsPerAxisText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of numPtsPerAxisText as text
            %        str2double(get(hObject,'String')) returns contents of numPtsPerAxisText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Menu selected function: placeMarkerLineAtDepartureUTContextMenu
        function placeMarkerLineAtDepartureUTContextMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to placeMarkerLineAtDepartureUTContextMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [x,~] = ginput(1);

            markers = get(handles.placeMarkerLineAtDepartureUTContextMenu,'UserData');
            markers(end+1,1) = x;
            markers(end,2) = -1;
            set(handles.placeMarkerLineAtDepartureUTContextMenu,'UserData',markers);

            plotMarkerLines(app, handles);
        end

        % Cell edit callback: waypointTable
        function waypointTable_CellEditCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to waypointTable (see GCBO)
            % eventdata  structure with the following fields (see UITABLE)
            %	Indices: row and column indices of the cell(s) edited
            %	PreviousData: previous data for the cell(s) edited
            %	EditData: string(s) entered by the user
            %	NewData: EditData or its converted form set on the Data property. Empty if Data was not changed
            %	Error: error string when failed to convert EditData to appropriate value for Data
            % handles    structure with handles and user data (see GUIDATA)
            waypointTable_CellSelectionCallback(app, event);
        end

        % Cell selection callback: waypointTable
        function waypointTable_CellSelectionCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to waypointTable (see GCBO)
            % eventdata  structure with the following fields (see UITABLE)
            %	Indices: row and column indices of the cell(s) currently selecteds
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.waypointTable,'UserData',eventdata.Indices);

            celBodyData = getappdata(handles.flyByPorkchopPlotterGUI,'celBodyData');

            wayPtBodies = {};
            waypts = get(handles.waypointTable,'Data');
            for(i=1:length(waypts))
                wayPtBodies{end+1} = celBodyData.(lower(waypts{i})); %#ok<AGROW>
            end

            selected = eventdata.Indices;
            if(isempty(selected))
                selected = size(wayPtBodies);
            end
            selected = selected(1);

            if(selected==length(wayPtBodies))
                set(handles.minFlightTimeText,'String','N/A');
                set(handles.maxFlightTimeText,'String','N/A');
                set(handles.maxNumRevsText,'String','N/A');

                set(handles.minFlightTimeText,'Enable','off');
                set(handles.maxFlightTimeText,'Enable','off');
                set(handles.maxNumRevsText,'Enable','off');
            else
                set(handles.minFlightTimeText,'Enable','on');
                set(handles.maxFlightTimeText,'Enable','on');
                set(handles.maxNumRevsText,'Enable','on');

                waypt1 = cap1stLetter(wayPtBodies{selected}.name);
                waypt2 = cap1stLetter(wayPtBodies{selected+1}.name);
                set(handles.flightTimeBndsPanel,'Title',[waypt1,' -> ',waypt2,' Flight Time Bounds']);

                bnds = get(handles.flightTimeBndsPanel,'UserData');
                [~, ~, secInDay, ~] = getSecondsInVariousTimeUnits();
                set(handles.minFlightTimeText,'String',num2str(bnds(selected,1)/secInDay));
                set(handles.maxFlightTimeText,'String',num2str(bnds(selected,2)/secInDay));
                set(handles.maxNumRevsText,'String',num2str(bnds(selected,3)));
            end
        end

        % Callback function: uitoggletool3
        function uitoggletool3_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable data tips based on the
            % tool's current state.
            state = app.uitoggletool3.State;
            datacursormode(app.flyByPorkchopPlotterGUI, char(state));
        end

        % Callback function: uitoggletool4
        function uitoggletool4_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool4.State;
            pan(app.flyByPorkchopPlotterGUI, char(state));
        end

        % Callback function: uitoggletool2
        function uitoggletool2_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.uitoggletool2.State;
            zoomModeObject = zoom(app.flyByPorkchopPlotterGUI);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Callback function: uitoggletool1
        function uitoggletool1_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.uitoggletool1.State;
            zoomModeObject = zoom(app.flyByPorkchopPlotterGUI);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.flyByPorkchopPlotterGUI = uifigure('Visible', 'off');
            colormap(app.flyByPorkchopPlotterGUI, 'parula');
            app.flyByPorkchopPlotterGUI.Name = 'Flyby Porkchop Plotter';
            app.flyByPorkchopPlotterGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.flyByPorkchopPlotterGUI.Tag = 'flyByPorkchopPlotterGUI';
            app.uitoolbar1 = uitoolbar(app.flyByPorkchopPlotterGUI);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uitoggletool1 = uitoggletool(app.uitoolbar1);
            app.uitoggletool1.Tag = 'uitoggletool1';
            app.uitoggletool1.Tooltip = 'Zoom In';
            app.uitoggletool1.ClickedCallback = createCallbackFcn(app, @uitoggletool1_ClickedCallback, true);
            app.uitoggletool1.Icon = 'zoomInToolbar_image.png';
            app.uitoggletool2 = uitoggletool(app.uitoolbar1);
            app.uitoggletool2.Tag = 'uitoggletool2';
            app.uitoggletool2.Tooltip = 'Zoom Out';
            app.uitoggletool2.ClickedCallback = createCallbackFcn(app, @uitoggletool2_ClickedCallback, true);
            app.uitoggletool2.Icon = 'zoomOutToolbar_image.png';
            app.uitoggletool4 = uitoggletool(app.uitoolbar1);
            app.uitoggletool4.Tag = 'uitoggletool4';
            app.uitoggletool4.Tooltip = 'Pan';
            app.uitoggletool4.ClickedCallback = createCallbackFcn(app, @uitoggletool4_ClickedCallback, true);
            app.uitoggletool4.Icon = 'panToolbar_image.png';
            app.uitoggletool3 = uitoggletool(app.uitoolbar1);
            app.uitoggletool3.Tag = 'uitoggletool3';
            app.uitoggletool3.Tooltip = 'Data Cursor';
            app.uitoggletool3.ClickedCallback = createCallbackFcn(app, @uitoggletool3_ClickedCallback, true);
            app.uitoggletool3.Icon = 'dataCursorToolbar_image.png';
            app.displayAxes = uiaxes(app.flyByPorkchopPlotterGUI);
            app.displayAxes.Tag = 'displayAxes';
            app.uipanel1 = uipanel(app.flyByPorkchopPlotterGUI);
            app.uipanel1.Title = 'General Parameters';
            app.uipanel1.Tag = 'uipanel1';
            app.centralBodyCombo = uidropdown(app.uipanel1);
            app.centralBodyCombo.Items = {'Pop-up Menu'};
            app.centralBodyCombo.ValueChangedFcn = createCallbackFcn(app, @centralBodyCombo_Callback, true);
            app.centralBodyCombo.Tag = 'centralBodyCombo';
            app.centralBodyCombo.Tooltip = 'This is the body about which the Initial Orbit is about.';
            app.centralBodyCombo.Value = 'Pop-up Menu';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Central Body';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Launch Window Open';
            app.launchWindowOpenText = uieditfield(app.uipanel1, 'text');
            app.launchWindowOpenText.ValueChangedFcn = createCallbackFcn(app, @launchWindowOpenText_Callback, true);
            app.launchWindowOpenText.Tag = 'launchWindowOpenText';
            app.launchWindowOpenText.Tooltip = 'Seconds from game start (right-click to enter as date)';
            app.launchWindowOpenText.Value = '0.0';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'sec';
            app.launchWinOpenLabel = uilabel(app.uipanel1);
            app.launchWinOpenLabel.Tag = 'launchWinOpenLabel';
            app.launchWinOpenLabel.Text = 'Date';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Launch Window Close';
            app.launchWindowCloseText = uieditfield(app.uipanel1, 'text');
            app.launchWindowCloseText.ValueChangedFcn = createCallbackFcn(app, @launchWindowCloseText_Callback, true);
            app.launchWindowCloseText.Tag = 'launchWindowCloseText';
            app.launchWindowCloseText.Tooltip = 'Seconds from game start (right-click to enter as date)';
            app.launchWindowCloseText.Value = '157680000';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'sec';
            app.launchWinCloseLabel = uilabel(app.uipanel1);
            app.launchWinCloseLabel.Tag = 'launchWinCloseLabel';
            app.launchWinCloseLabel.Text = 'Date';
            app.uipanel2 = uipanel(app.flyByPorkchopPlotterGUI);
            app.uipanel2.Title = 'Waypoints';
            app.uipanel2.Tag = 'uipanel2';
            app.waypointTable = uitable(app.uipanel2);
            app.waypointTable.ColumnEditable = true;
            app.waypointTable.CellEditCallback = createCallbackFcn(app, @waypointTable_CellEditCallback, true);
            app.waypointTable.CellSelectionCallback = createCallbackFcn(app, @waypointTable_CellSelectionCallback, true);
            app.waypointTable.Tag = 'waypointTable';
            app.addWayPointRowButton = uibutton(app.uipanel2, 'push');
            app.addWayPointRowButton.ButtonPushedFcn = createCallbackFcn(app, @addWayPointRowButton_Callback, true);
            app.addWayPointRowButton.Tag = 'addWayPointRowButton';
            app.addWayPointRowButton.Text = '+';
            app.deleteWayPointRowButton = uibutton(app.uipanel2, 'push');
            app.deleteWayPointRowButton.ButtonPushedFcn = createCallbackFcn(app, @deleteWayPointRowButton_Callback, true);
            app.deleteWayPointRowButton.Tag = 'deleteWayPointRowButton';
            app.deleteWayPointRowButton.Text = '-';
            app.uipanel3 = uipanel(app.flyByPorkchopPlotterGUI);
            app.uipanel3.Title = 'Options';
            app.uipanel3.Tag = 'uipanel3';
            app.text8 = uilabel(app.uipanel3);
            app.text8.Tag = 'text8';
            app.text8.Tooltip = 'Number of Data Points Along Each Axis';
            app.text8.Text = 'Num. Pts. Per Axis';
            app.numPtsPerAxisText = uieditfield(app.uipanel3, 'text');
            app.numPtsPerAxisText.ValueChangedFcn = createCallbackFcn(app, @numPtsPerAxisText_Callback, true);
            app.numPtsPerAxisText.Tag = 'numPtsPerAxisText';
            app.numPtsPerAxisText.Tooltip = 'As this increases, the fidelity of the resulting plot increases, but so does memory usage.';
            app.numPtsPerAxisText.Value = '30';
            app.recomputePlotOnZoomCheckbox = uicheckbox(app.uipanel3);
            app.recomputePlotOnZoomCheckbox.Tag = 'recomputePlotOnZoomCheckbox';
            app.recomputePlotOnZoomCheckbox.Enable = 'off';
            app.recomputePlotOnZoomCheckbox.Text = 'Recompute Plot On Zoom/Pan';
            app.includeDepartureVInfCheckbox = uicheckbox(app.uipanel3);
            app.includeDepartureVInfCheckbox.Tag = 'includeDepartureVInfCheckbox';
            app.includeDepartureVInfCheckbox.Text = 'Consider Departure V_Inf';
            app.includeDepartureVInfCheckbox.Value = true;
            app.includeArrivalVInfCheckbox = uicheckbox(app.uipanel3);
            app.includeArrivalVInfCheckbox.Tag = 'includeArrivalVInfCheckbox';
            app.includeArrivalVInfCheckbox.Text = 'Consider Arrival V_Inf';
            app.maxDvToDisplayCombo = uidropdown(app.uipanel3);
            app.maxDvToDisplayCombo.Items = {'100%', '90 %', '80 %', '70 %', '60 %', '50 %', '40 %', '30 %', '20 %', '10 %', '5 %', '2 %', '1 %', '0.1 %'};
            app.maxDvToDisplayCombo.ValueChangedFcn = createCallbackFcn(app, @maxDvToDisplayCombo_Callback, true);
            app.maxDvToDisplayCombo.Tag = 'maxDvToDisplayCombo';
            app.maxDvToDisplayCombo.Tooltip = 'Percent of maximum delta-v to display on porkchop plot.';
            app.maxDvToDisplayCombo.Value = '100%';
            app.text15 = uilabel(app.uipanel3);
            app.text15.Tag = 'text15';
            app.text15.Text = '% of Max DV to Display';
            app.text16 = uilabel(app.uipanel3);
            app.text16.Tag = 'text16';
            app.text16.Tooltip = 'Number of Levels in Contour Plots';
            app.text16.Text = 'Num. Contour Lvls';
            app.numContourLvlsText = uieditfield(app.uipanel3, 'text');
            app.numContourLvlsText.ValueChangedFcn = createCallbackFcn(app, @numContourLvlsText_Callback, true);
            app.numContourLvlsText.Tag = 'numContourLvlsText';
            app.numContourLvlsText.Tooltip = 'Number of Levels in Contour Plots';
            app.numContourLvlsText.Value = '20';
            app.computeFlybyPorkchopPlotButton = uibutton(app.flyByPorkchopPlotterGUI, 'push');
            app.computeFlybyPorkchopPlotButton.ButtonPushedFcn = createCallbackFcn(app, @computeFlybyPorkchopPlotButton_Callback, true);
            app.computeFlybyPorkchopPlotButton.Tag = 'computeFlybyPorkchopPlotButton';
            app.computeFlybyPorkchopPlotButton.Tooltip = 'Generates the porkchop plot.  Go get coffee, this may take a while.';
            app.computeFlybyPorkchopPlotButton.Text = 'Compute Flyby Porkchop Plot';
            app.dispAxesSelectSlider = uislider(app.flyByPorkchopPlotterGUI);
            app.dispAxesSelectSlider.Limits = [2 3];
            app.dispAxesSelectSlider.MajorTicks = [];
            app.dispAxesSelectSlider.ValueChangedFcn = createCallbackFcn(app, @dispAxesSelectSlider_Callback, true);
            app.dispAxesSelectSlider.MinorTicks = [];
            app.dispAxesSelectSlider.Tag = 'dispAxesSelectSlider';
            app.dispAxesSelectSlider.Tooltip = 'Plot selection scrollbar.  Use this to change which leg of the trajetory is plotted on the Y-axis of the plot.';
            app.dispAxesSelectSlider.Value = 2;
            app.flightTimeBndsPanel = uipanel(app.flyByPorkchopPlotterGUI);
            app.flightTimeBndsPanel.Title = 'BODY1 -> BODY2 Flight Time Bounds';
            app.flightTimeBndsPanel.Tag = 'flightTimeBndsPanel';
            app.minFlightTimeText = uieditfield(app.flightTimeBndsPanel, 'text');
            app.minFlightTimeText.ValueChangedFcn = createCallbackFcn(app, @minFlightTimeText_Callback, true);
            app.minFlightTimeText.Tag = 'minFlightTimeText';
            app.minFlightTimeText.Value = '1';
            app.text11 = uilabel(app.flightTimeBndsPanel);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Minimum';
            app.text12 = uilabel(app.flightTimeBndsPanel);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Maximum';
            app.maxFlightTimeText = uieditfield(app.flightTimeBndsPanel, 'text');
            app.maxFlightTimeText.ValueChangedFcn = createCallbackFcn(app, @maxFlightTimeText_Callback, true);
            app.maxFlightTimeText.Tag = 'maxFlightTimeText';
            app.maxFlightTimeText.Value = '100';
            app.text13 = uilabel(app.flightTimeBndsPanel);
            app.text13.Tag = 'text13';
            app.text13.Text = 'days';
            app.text14 = uilabel(app.flightTimeBndsPanel);
            app.text14.Tag = 'text14';
            app.text14.Text = 'days';
            app.text17 = uilabel(app.flightTimeBndsPanel);
            app.text17.Tag = 'text17';
            app.text17.Tooltip = 'Number of revolutions the solver will go around the central body before arriving at the next waypoint.  Must be an integer greater than or equal to zero.';
            app.text17.Text = 'Num. Revolutions';
            app.maxNumRevsText = uieditfield(app.flightTimeBndsPanel, 'text');
            app.maxNumRevsText.ValueChangedFcn = createCallbackFcn(app, @maxNumRevsText_Callback, true);
            app.maxNumRevsText.Tag = 'maxNumRevsText';
            app.maxNumRevsText.Tooltip = 'Number of revolutions the solver will go around the central body before arriving at the next waypoint.  Must be an integer greater than or equal to zero.';
            app.maxNumRevsText.Value = '0';
            app.LWindowContextMenu = uicontextmenu(app.flyByPorkchopPlotterGUI);
            app.LWindowContextMenu.Tag = 'LWindowContextMenu';
            app.enterUTAsDateTime = uimenu(app.LWindowContextMenu);
            app.enterUTAsDateTime.MenuSelectedFcn = createCallbackFcn(app, @enterUTAsDateTime_Callback, true);
            app.enterUTAsDateTime.Text = 'Enter UT As Date/Time';
            app.enterUTAsDateTime.Tag = 'enterUTAsDateTime';
            % Assign app.LWindowContextMenu
            app.launchWindowOpenText.ContextMenu = app.LWindowContextMenu;
            app.launchWindowCloseText.ContextMenu = app.LWindowContextMenu;
            app.dispAxesContextMenu = uicontextmenu(app.flyByPorkchopPlotterGUI);
            app.dispAxesContextMenu.Tag = 'dispAxesContextMenu';
            app.placeMarkerLineAtDepartureUTContextMenu = uimenu(app.dispAxesContextMenu);
            app.placeMarkerLineAtDepartureUTContextMenu.MenuSelectedFcn = createCallbackFcn(app, @placeMarkerLineAtDepartureUTContextMenu_Callback, true);
            app.placeMarkerLineAtDepartureUTContextMenu.Text = 'Place Marker Line at Departure UT';
            app.placeMarkerLineAtDepartureUTContextMenu.Tag = 'placeMarkerLineAtDepartureUTContextMenu';
            app.clearMarkerLinesFromPlotContextMenu = uimenu(app.dispAxesContextMenu);
            app.clearMarkerLinesFromPlotContextMenu.MenuSelectedFcn = createCallbackFcn(app, @clearMarkerLinesFromPlotContextMenu_Callback, true);
            app.clearMarkerLinesFromPlotContextMenu.Text = 'Clear Marker Lines From Plot';
            app.clearMarkerLinesFromPlotContextMenu.Tag = 'clearMarkerLinesFromPlotContextMenu';
            % Show the figure after all components are created
            app.flyByPorkchopPlotterGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = flyByPorkchopPlotterGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.flyByPorkchopPlotterGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)flyByPorkchopPlotterGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.flyByPorkchopPlotterGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.flyByPorkchopPlotterGUI)
        end
    end
end