classdef helpAboutGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        versionLabel  matlab.ui.control.Label
        uipanel1      matlab.ui.container.Panel
        text14        matlab.ui.control.Label
        text13        matlab.ui.control.Label
        text12        matlab.ui.control.Label
        text11        matlab.ui.control.Label
        text9         matlab.ui.control.Label
        text7         matlab.ui.control.Label
        text6         matlab.ui.control.Label
        text5         matlab.ui.control.Label
        text3         matlab.ui.control.Label
        logoAxes      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function helpAboutGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to helpAboutGUI (see VARARGIN)
            
            % Choose default command line output for helpAboutGUI
            handles.output = hObject;
            centerUIFigure(app.figure1);
            
            % Update handles structure
            guidata(hObject, handles);
            
            % str = sprintf(['KSP\nTrajectory Optimization Tool ',getKSPTOTVersionNumStr()]);
            % set(handles.titleLabel,'String',str);
            
            handles.versionLabel.String = ['v',getKSPTOTVersionNumStr()];
            
            if(ispc)
                rgb = imread('images\testlogo.png');
            else
                rgb = imread('images/testlogo.png');
            end
            image(rgb,'Parent',handles.logoAxes);
            axis off;
            axis image;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'About KSP TOT';
            app.figure1.Icon = 'logoSquare_48px_transparentBg.png';
            app.figure1.Tag = 'figure1';
            app.logoAxes = uiaxes(app.figure1);
            app.logoAxes.XTick = zeros(1,0);
            app.logoAxes.XTickLabel = {'0  '; '0.5'; '1  '};
            app.logoAxes.YTick = zeros(1,0);
            app.logoAxes.YTickLabel = {'0  '; '0.5'; '1  '};
            app.logoAxes.Tag = 'logoAxes';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Credits';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Programming / GUI Design';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Arrowstar';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'KSPTOT Logo';
            app.text9 = uilabel(app.uipanel1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Special Thanks';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Jeffery Cheung (/u/ jeff25194)';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Tag = 'text12';
            app.text12.Text = {'diomedea'; 'togfox'; 'bk2w'; 'RadarManFromTheMoon'};
            app.text13 = uilabel(app.uipanel1);
            app.text13.Tag = 'text13';
            app.text13.Text = {'voneiden'; 'TranceaddicT'; 'Drew_Kerman'; 'phoenix_ca'};
            app.text14 = uilabel(app.uipanel1);
            app.text14.Tag = 'text14';
            app.text14.Text = {'...and all others who have helped test and find '; 'bugs over the years.  Thanks!'};
            app.versionLabel = uilabel(app.figure1);
            app.versionLabel.Tag = 'versionLabel';
            app.versionLabel.Text = 'versionString';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = helpAboutGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)helpAboutGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end