classdef importOrbitGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        importOrbitGUI  matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        GridLayout2     matlab.ui.container.GridLayout
        uipanel1        matlab.ui.container.Panel
        GridLayout3     matlab.ui.container.GridLayout
        text4_2         matlab.ui.control.Label
        text4           matlab.ui.control.Label
        text3           matlab.ui.control.Label
        text2           matlab.ui.control.Label
        text1           matlab.ui.control.Label
        argText         matlab.ui.control.EditField
        raanText        matlab.ui.control.EditField
        incText         matlab.ui.control.EditField
        eccText         matlab.ui.control.EditField
        smaText         matlab.ui.control.EditField
        text9           matlab.ui.control.Label
        text8           matlab.ui.control.Label
        text7           matlab.ui.control.Label
        text6           matlab.ui.control.Label
        text5           matlab.ui.control.Label
        useSelOrbitBtn  matlab.ui.control.Button
        orbitListBox    matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput
    end
    
    methods (Access = private)
        
        function saveAndClose(app, handles)
            try
                listboxUserData = get(handles.orbitListBox,'UserData');
                importOrbitGUIUserData = get(handles.importOrbitGUI,'UserData');
                orbits = listboxUserData{1};
                
                app.output.output{1} = orbits{get(handles.orbitListBox, 'Value')};
                
                if(~isempty(importOrbitGUIUserData))
                    app.output.output{2} = importOrbitGUIUserData{1,1};
                else
                    app.output.output{2} = [];
                end
                
                try
                    app.output.output{3} = listboxUserData{2}{get(handles.orbitListBox, 'Value')};
                catch ME %#ok<NASGU> 
                    app.output.output{3} = '';
                end
            catch ME %#ok<NASGU> 
                app.output.output{1} = [];
                app.output.output{2} = [];
                app.output.output{3} = '';
            end
            
            close(handles.importOrbitGUI);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function importOrbitGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app);

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to importOrbitGUI (see VARARGIN)

            % Choose default command line output for importOrbitGUI
            handles.output = hObject;

            % Update handles structure
            guidata(hObject, handles);

            %Possible fix for people with display issues.
%             checkForCharUnitsInGUI(hObject);

            centerUIFigure(app.importOrbitGUI);
            applySelectedThemeToApp(app);
            
            app.importOrbitGUI.Visible = 'off'; drawnow;

            dataSource = varargin{1}; %-1 = KSP TOT Connect, anything else is from SFS file
            if(dataSource == -1)
                hRetDataDlg = helpdlg('Retrieving vessel data from KSPTOT Connect...', 'Retrieving Data');
                try
                    guids = readManyStringsFromKSPTOTConnect('GetVesselIDList', '', 32, true);
                catch ME %#ok<NASGU> 
                    if(ishandle(hRetDataDlg))
                        close(hRetDataDlg);
                    end
                    if(ishandle(hObject))
                        close(hObject);
                    end
                    errordlg('Could not retrieve orbit data from KSPTOTConnect.  Is KSP and the KSPTOTConnect plugin running?', 'Retrieval Failure');
                    return;
                end

                orbits = {};
                for(i=1:length(guids)) %#ok<*NO4LP>
                    guid = guids{i};

                    orbitRow = getSingularOrbitFromKSPTOTConnect(guid);
                    orbits(i,:) = orbitRow; %#ok<AGROW>
                end
                if(ishandle(hRetDataDlg))
                    close(hRetDataDlg);
                end
            else
                prevPathName = varargin{2};
                if(~isempty(prevPathName))
                    defaultName = [prevPathName,'persistent.sfs'];
                else
                    defaultName = 'persistent.sfs';
                end

                [FileName,PathName] = uigetfile({'*.sfs','KSP Persistence Files (*.sfs)'},'Select SFS file to load...',defaultName);
                if(not(ischar(FileName)) || not(ischar(PathName)))
                    close(hObject);
                    return;
                end
                filePath = strcat(PathName,FileName);
                orbits = parseSFSForOrbits(filePath);

                set(hObject, 'UserData', {PathName});
            end

            listBoxStrArr = {};
            orbitsNotLanded = {};
            for(i=1:size(orbits,1))
                orbit = orbits(i,:);
                if(not(strcmpi(orbit{2},'LANDED')) && not(strcmpi(orbit{2},'SPLASHED')) && not(strcmpi(orbit{2},'PRELAUNCH')))
                    if(~isempty(orbit{1}))
                        listBoxStrArr{end+1} = orbit{1}; %#ok<AGROW> 
                        orbitsNotLanded{end+1} = orbit; %#ok<AGROW> 
                    end
                end
            end
            listboxUserData = {orbitsNotLanded, listBoxStrArr};
            set(handles.orbitListBox, 'String', char(listBoxStrArr));
            set(handles.orbitListBox, 'UserData', listboxUserData);

            app.output = varargin{3};
            
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            orbitListBox_Callback(app, evt);

            app.importOrbitGUI.Visible = 'on'; drawnow;
            
            % UIWAIT makes importOrbitGUI wait for user response (see UIRESUME)
             uiwait(handles.importOrbitGUI);
        end

        % Value changed function: orbitListBox
        function orbitListBox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.orbitListBox;
            
            % hObject    handle to orbitListBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns orbitListBox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from orbitListBox
            listboxUserData = get(hObject,'UserData');
            orbits = listboxUserData{1};

            orbitNum = get(hObject,'Value');
            if(length(orbits) >= orbitNum)
                orbit = orbits{orbitNum};

                set(handles.smaText, 'String', num2str(orbit{3},9));
                set(handles.eccText, 'String', num2str(orbit{4},9));
                set(handles.incText, 'String', num2str(orbit{5},9));
                set(handles.raanText, 'String', num2str(orbit{6},9));
                set(handles.argText, 'String', num2str(orbit{7},9));
            end
        end

        % Button pushed function: useSelOrbitBtn
        function useSelOrbitBtn_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to useSelOrbitBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
%             uiresume(handles.importOrbitGUI);
            app.saveAndClose(handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.importOrbitGUI = uifigure('Visible', 'off');
            colormap(app.importOrbitGUI, 'parula');
            app.importOrbitGUI.Name = 'Import Orbit from SFS';
            app.importOrbitGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.importOrbitGUI.Tag = 'importOrbitGUI';
            app.GridLayout = uigridlayout(app.importOrbitGUI);
            app.orbitListBox = uilistbox(app.GridLayout);
            app.orbitListBox.Items = {};
            app.orbitListBox.ValueChangedFcn = createCallbackFcn(app, @orbitListBox_Callback, true);
            app.orbitListBox.Tag = 'orbitListBox';
            app.orbitListBox.Tooltip = 'A list of all vehicles found in the SFS file.';
            app.orbitListBox.Value = {};
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.useSelOrbitBtn = uibutton(app.GridLayout2, 'push');
            app.useSelOrbitBtn.ButtonPushedFcn = createCallbackFcn(app, @useSelOrbitBtn_Callback, true);
            app.useSelOrbitBtn.Tag = 'useSelOrbitBtn';
            app.useSelOrbitBtn.Tooltip = 'Uses the selected orbit in the application.';
            app.useSelOrbitBtn.Text = 'Use Selected Orbit';
            app.uipanel1 = uipanel(app.GridLayout2);
            app.uipanel1.Title = 'Imported Orbit Information';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.text5 = uilabel(app.GridLayout3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'SMA';
            app.text6 = uilabel(app.GridLayout3);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Ecc.';
            app.text7 = uilabel(app.GridLayout3);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Inc.';
            app.text8 = uilabel(app.GridLayout3);
            app.text8.Tag = 'text8';
            app.text8.Text = 'RAAN';
            app.text9 = uilabel(app.GridLayout3);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Arg. Peri.';
            app.smaText = uieditfield(app.GridLayout3, 'text');
            app.smaText.Tag = 'smaText';
            app.smaText.Enable = 'off';
            app.smaText.Tooltip = 'Semi-major axis of imported orbit.  Not editable.';
            app.smaText.Value = '0.0';
            app.eccText = uieditfield(app.GridLayout3, 'text');
            app.eccText.Tag = 'eccText';
            app.eccText.Enable = 'off';
            app.eccText.Tooltip = 'Eccentricity of imported orbit.  Not editable.';
            app.eccText.Value = '0.0';
            app.incText = uieditfield(app.GridLayout3, 'text');
            app.incText.Tag = 'incText';
            app.incText.Enable = 'off';
            app.incText.Tooltip = 'Inclination of imported orbit.  Not editable.';
            app.incText.Value = '0.0';
            app.raanText = uieditfield(app.GridLayout3, 'text');
            app.raanText.Tag = 'raanText';
            app.raanText.Enable = 'off';
            app.raanText.Tooltip = 'Right ascension of the accending node of imported orbit.  Not editable.';
            app.raanText.Value = '0.0';
            app.argText = uieditfield(app.GridLayout3, 'text');
            app.argText.Tag = 'argText';
            app.argText.Enable = 'off';
            app.argText.Tooltip = 'Argument of periapse of imported orbit.  Not editable.';
            app.argText.Value = '0.0';
            app.text1 = uilabel(app.GridLayout3);
            app.text1.Tag = 'text1';
            app.text1.Text = 'km';
            app.text2 = uilabel(app.GridLayout3);
            app.text2.Tag = 'text2';
            app.text2.Text = 'deg';
            app.text3 = uilabel(app.GridLayout3);
            app.text3.Tag = 'text3';
            app.text3.Text = 'deg';
            app.text4 = uilabel(app.GridLayout3);
            app.text4.Tag = 'text4';
            app.text4.Text = 'deg';
            app.text4_2 = uilabel(app.GridLayout3);
            app.text4_2.Tag = 'text4';
            app.text4_2.Text = '';
            % Show the figure after all components are created
            app.importOrbitGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = importOrbitGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.importOrbitGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)importOrbitGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.importOrbitGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.importOrbitGUI)
        end
    end
end