classdef mainGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainGUIFigure                   matlab.ui.Figure
        fileMenu                        matlab.ui.container.Menu
        loadBodiesFromFile              matlab.ui.container.Menu
        createNewBodiesFileFromKSP      matlab.ui.container.Menu
        edit                            matlab.ui.container.Menu
        optionsMenu                     matlab.ui.container.Menu
        optionTimeSystemMenu            matlab.ui.container.Menu
        useEarthTimeMenu                matlab.ui.container.Menu
        useKerbinTimeMenu               matlab.ui.container.Menu
        gravParamMenu                   matlab.ui.container.Menu
        useKspGmMenu                    matlab.ui.container.Menu
        useRssLikeGmMenu                matlab.ui.container.Menu
        ConfigureCelestialBodyStateCacheMenu  matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        EditThemesMenu                  matlab.ui.container.Menu
        SetThemeMenu                    matlab.ui.container.Menu
        toolsMenu                       matlab.ui.container.Menu
        missionPlanningMenu             matlab.ui.container.Menu
        missionArchitectMenu            matlab.ui.container.Menu
        launchVehicleDesignerMenu       matlab.ui.container.Menu
        maneuverPlanningMenu            matlab.ui.container.Menu
        flybyManSeq                     matlab.ui.container.Menu
        multiFlyByManeuverSequencerMenu  matlab.ui.container.Menu
        multiFlybyPorkchopPlotterMenu   matlab.ui.container.Menu
        rendezvousManSeq                matlab.ui.container.Menu
        optimal2BurnOrbitChange         matlab.ui.container.Menu
        manExeAssist                    matlab.ui.container.Menu
        vehicleSizingMenu               matlab.ui.container.Menu
        vehicleSizingToolMenu           matlab.ui.container.Menu
        CelestialBodyCatalogMenu        matlab.ui.container.Menu
        Untitled_1                      matlab.ui.container.Menu
        rtsUploadManeuver               matlab.ui.container.Menu
        mccRTS                          matlab.ui.container.Menu
        helpMenu                        matlab.ui.container.Menu
        aboutMenu                       matlab.ui.container.Menu
        uitoolbar1                      matlab.ui.container.Toolbar
        uitoggletool1                   matlab.ui.container.toolbar.ToggleTool
        uitoggletool2                   matlab.ui.container.toolbar.ToggleTool
        uitoggletool3                   matlab.ui.container.toolbar.ToggleTool
        uitoggletool4                   matlab.ui.container.toolbar.ToggleTool
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        Panel_3                         matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        testPorkChopper                 matlab.ui.control.Button
        computeDepartBurn               matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        porkchopPlotAxes                matlab.ui.control.UIAxes
        GridLayout4                     matlab.ui.container.GridLayout
        ArrivalParametersPanel          matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        GridLayout9                     matlab.ui.container.GridLayout
        arrivalBodyEarliestTimeText     matlab.ui.control.EditField
        text8                           matlab.ui.control.Label
        arrivalBodyCombo                matlab.ui.control.DropDown
        arriveDateText                  matlab.ui.control.Label
        text6                           matlab.ui.control.Label
        text5                           matlab.ui.control.Label
        DepartureParametersPanel        matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        departDateText                  matlab.ui.control.Label
        text3                           matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        departBodyEarliestTimeText      matlab.ui.control.EditField
        text7                           matlab.ui.control.Label
        departBodyCombo                 matlab.ui.control.DropDown
        text4                           matlab.ui.control.Label
        TransferCentralBodyPanel        matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        centralBodyCombo                matlab.ui.control.DropDown
        synPeriodLabelText              matlab.ui.control.Label
        porkchopPlotTypeButtonGroup     matlab.ui.container.ButtonGroup
        departArrivalDeltaVRadioButton  matlab.ui.control.RadioButton
        arrivalDeltaVRadioButton        matlab.ui.control.RadioButton
        departDeltaVRadioButton         matlab.ui.control.RadioButton
        statusText                      matlab.ui.control.TextArea
        enterUTAsDateTime               matlab.ui.container.ContextMenu
        enterUTAsDateTime_1             matlab.ui.container.Menu
        getUTFromKSP                    matlab.ui.container.Menu
        statusBoxMenu                   matlab.ui.container.ContextMenu
        resetStatusBox                  matlab.ui.container.Menu
        porkchopPlotAxesMenu            matlab.ui.container.ContextMenu
        recenterPlotAt                  matlab.ui.container.Menu
    end


    properties (Access = private)
        celBodyData % Description
        appThemer AppThemer % Description
    end

    methods (Access = private)
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.

            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool1, app.uitoggletool2, app.uitoggletool3, app.uitoggletool4];
            interactiveTools(event.Source == interactiveTools) = [];

            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');

            % Set figure interactions to 'off'.
            datacursormode(app.mainGUIFigure, 'off')
            rotate3d(app.mainGUIFigure, 'off');
            pan(app.mainGUIFigure, 'off');
            zoom(app.mainGUIFigure,'off');
        end

        
        function setThemeMenuCallback(app, src, evt, theme)
            app.appThemer.selTheme = theme;
            app.appThemer.themeAllOpenApps();
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function mainGUI_OpeningFcn(app, varargin)
            global options_UseEarthTimeSystem options_gravParamType GLOBAL_AppThemer %#ok<GVMIS>
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app);

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to mainGUI (see VARARGIN)

            % Choose default command line output for mainGUI
            handles.output = hObject;
            %             set(hObject,'Visible','off');

            % Update handles structure
            guidata(hObject, handles);

            centerUIFigure(app.mainGUIFigure);

            %Possible fix for people with display issues.
            %             checkForCharUnitsInGUI(hObject);
            %             drawnow;

            %init GUI data
            app.celBodyData = varargin{1};
            % bodyNames = varargin{2};
            mainGUIUserData = cell(10,10);
            mainGUIUserData{1,1} = app.celBodyData;

            %Application Options
            appOptions = varargin{4};

            options = struct();
            options.plotmaxdeltav = appOptions.ksptot.plotmaxdeltav;
            options.porkchopptsaxes = appOptions.ksptot.porkchopptsaxes;
            options.porkchopnumsynperiods = appOptions.ksptot.porkchopnumsynperiods;
            options.departplotnumoptiters = appOptions.ksptot.departplotnumoptiters;
            options.quant2opt = appOptions.ksptot.quant2opt;
            mainGUIUserData{1,9} = options;
            mainGUIUserData{1,10} = handles.porkchopPlotAxes;

            if(strcmpi(appOptions.ksptot.timesystem,'Earth'))
                options_UseEarthTimeSystem = true;
            else
                options_UseEarthTimeSystem = false;
            end

            options_gravParamType = appOptions.ksptot.gravparamtype;

            set(hObject,'HandleVisibility','on');
            %             set(hObject,'visible','off');
            % axes(mainGUIUserData{1,10});

            set(hObject,'UserData',mainGUIUserData);
            setappdata(hObject,'openToolWindows',matlab.ui.Figure.empty(0,1));
            setappdata(hObject,'appOptions',appOptions);

            %Set up plot
            plotPorkChopPlot(hObject);

            %init GUI
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            departBodyEarliestTimeText_Callback(app, evt);
            arrivalBodyEarliestTimeText_Callback(app, evt);
            set(handles.flybyManSeq,'visible','off');

            %Set up combo boxes
            cullArriveDepartCombosPerCB(hObject);
            departBodyCombo_Callback(app, evt);
            arrivalBodyCombo_Callback(app, evt);

            %Set up status text box
            % hStatusBox = findobj(hObject,'Tag','statusText');
            set(handles.statusText, 'String', setINIStatusBoxMsg());

            %Handle app theming here
            if(exist('appThemeData.mat','file'))
                load('appThemeData.mat','appThemeData');
                app.appThemer = appThemeData;

            else
                app.appThemer = AppThemer();

                appThemeData = app.appThemer;
                save('appThemeData.mat','appThemeData');
            end
            app.appThemer.themeAllOpenApps();
            GLOBAL_AppThemer = app.appThemer;
        end

        % Menu selected function: aboutMenu
        function aboutMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to aboutMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %                 helpAboutGUI();
            helpAboutGUI_App();
        end

        % Value changed function: arrivalBodyCombo
        function arrivalBodyCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = app.arrivalBodyCombo;

            % hObject    handle to arrivalBodyCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns arrivalBodyCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from arrivalBodyCombo
            sel = hObject.Value;
            %             ylabel(handles.porkchopPlotAxes,[sel, ' Arrival Time (UT) [day]']);

            %             userData = get(handles.mainGUIFigure,'UserData');
            %             celBodyData = userData{1,1};

            arriveBody = lower(sel);
            arrivalBodyInfo = app.celBodyData.(arriveBody);

            contents = cellstr(get(handles.departBodyCombo,'String'));
            sel = contents{get(handles.departBodyCombo,'Value')};
            departBody = lower(sel);
            departBodyInfo = app.celBodyData.(departBody);

            contents = cellstr(get(handles.centralBodyCombo,'String'));
            sel = contents{get(handles.centralBodyCombo,'Value')};
            cenBody = lower(sel);
            gmu = app.celBodyData.(cenBody).gm;

            if(strcmp(arriveBody, departBody))
                set(handles.synPeriodLabelText, 'String', '');
            else
                departBodyMeanMotion = computeMeanMotion(departBodyInfo.sma, gmu);
                arrivalBodyMeanMotion = computeMeanMotion(arrivalBodyInfo.sma, gmu);
                synPeriod = computeSynodicPeriod(departBodyMeanMotion, arrivalBodyMeanMotion);
                [~, ~, secInDay, ~] = getSecondsInVariousTimeUnits();
                synPeriodDays = synPeriod/secInDay;

                set(handles.synPeriodLabelText, 'String', ['Synodic Period: ', num2str(synPeriodDays), ' days']);
            end
        end

        % Value changed function: arrivalBodyEarliestTimeText
        function arrivalBodyEarliestTimeText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = app.arrivalBodyEarliestTimeText;

            % hObject    handle to arrivalBodyEarliestTimeText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of arrivalBodyEarliestTimeText as text
            %        str2double(get(hObject,'String')) returns contents of arrivalBodyEarliestTimeText as a double
            newInput = get(hObject,'Value');

            newInput = attemptStrEval(newInput);

            inputSec = str2double(newInput);
            if(checkStrIsNumeric(newInput) && inputSec>=0.0)
                [year, day, hour, minute, sec] = convertSec2YearDayHrMnSec(inputSec);
                dateStr = formDateStr(year, day, hour, minute, sec);
                set(handles.arriveDateText, 'String', dateStr);
                set(hObject,'Value', num2str(inputSec));
            else

            end
        end

        % Value changed function: centralBodyCombo
        function centralBodyCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to centralBodyCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns centralBodyCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from centralBodyCombo
            cullArriveDepartCombosPerCB(handles.mainGUIFigure);
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            departBodyCombo_Callback(app, evt);
            arrivalBodyCombo_Callback(app, evt);
        end

        % Button pushed function: computeDepartBurn
        function computeDepartBurn_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to computeDepartBurn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cDApp = computeDepartureGUI_App(handles.mainGUIFigure, handles.porkchopPlotAxes, app.mainGUIFigure);

            openToolWindows = getappdata(handles.mainGUIFigure,'openToolWindows');
            openToolWindows = cleanOpenToolWindowsArr(openToolWindows);
            openToolWindows(end+1) = cDApp.computeDepartBurnFig;
            setappdata(handles.mainGUIFigure,'openToolWindows',openToolWindows);
        end

        % Menu selected function: createNewBodiesFileFromKSP
        function createNewBodiesFileFromKSP_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to createNewBodiesFileFromKSP (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h = warndlg('Please make sure KSP is running in the Flight Scene with the KSPTOTConnect plugin loaded before continuing.','Ensure KSP Running','modal');
            uiwait(h);
            [FileName,PathName] = getBodiesINIFileFromKSP();

            if(~isempty(FileName) && ~isempty(PathName))
                helpstring = sprintf('New bodies file created at:\n%s\n\nPlease use the File -> Load Bodies From File menu item to load the new file into KSP TOT.',[PathName,FileName]);
                helpdlg(helpstring,'Bodies File Created');
            end
        end

        % Value changed function: departBodyCombo
        function departBodyCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            hObject = app.departBodyCombo;

            % hObject    handle to departBodyCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns departBodyCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from departBodyCombo
            sel = hObject.Value;
            %             xlabel(handles.porkchopPlotAxes,[sel, ' Departure Time (UT) [day]']);

            %             userData = get(handles.mainGUIFigure,'UserData');
            %             celBodyData = userData{1,1};

            departBody = strtrim(lower(sel));
            departBodyInfo = app.celBodyData.(departBody);

            contents = cellstr(get(handles.arrivalBodyCombo,'String'));
            sel = contents{get(handles.arrivalBodyCombo,'Value')};
            arriveBody = strtrim(lower(sel));
            arrivalBodyInfo = app.celBodyData.(arriveBody);

            contents = cellstr(get(handles.centralBodyCombo,'String'));
            sel = contents{get(handles.centralBodyCombo,'Value')};
            cenBody = strtrim(lower(sel));
            gmu = app.celBodyData.(cenBody).gm;

            if(strcmp(arriveBody, departBody))
                set(handles.synPeriodLabelText, 'String', '');
            else
                [~, ~, secInDay, ~] = getSecondsInVariousTimeUnits();

                departBodyMeanMotion = computeMeanMotion(departBodyInfo.sma, gmu);
                arrivalBodyMeanMotion = computeMeanMotion(arrivalBodyInfo.sma, gmu);
                synPeriod = computeSynodicPeriod(departBodyMeanMotion, arrivalBodyMeanMotion);
                synPeriodDays = synPeriod/secInDay;

                set(handles.synPeriodLabelText, 'String', ['Synodic Period: ', num2str(synPeriodDays), ' days']);
            end
        end

        % Value changed function: departBodyEarliestTimeText
        function departBodyEarliestTimeText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = app.departBodyEarliestTimeText;

            % hObject    handle to departBodyEarliestTimeText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of departBodyEarliestTimeText as text
            %        str2double(get(hObject,'String')) returns contents of departBodyEarliestTimeText as a double
            newInput = get(hObject,'Value');

            newInput = attemptStrEval(newInput);

            inputSec = str2double(newInput);
            if(checkStrIsNumeric(newInput) && inputSec>=0.0)
                [year, day, hour, minute, sec] = convertSec2YearDayHrMnSec(inputSec);
                dateStr = formDateStr(year, day, hour, minute, sec);
                set(handles.departDateText, 'String', dateStr);
                set(hObject,'Value', num2str(inputSec));
            else

            end
        end

        % Menu selected function: enterUTAsDateTime_1
        function enterUTAsDateTime_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            %             hObject = app.enterUTAsDateTime;

            % hObject    handle to enterUTAsDateTime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %             secUT = enterUTAsDateTimeGUI(str2double(get(gco, 'Value')));

            output2 = AppDesignerGUIOutput({-1});
            enterUTAsDateTimeGUI_App(str2double(get(gco, 'Value')), output2);
            secUT = output2.output{1};

            if(secUT >= 0)
                set(gco, 'Value', num2str(secUT));
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                departBodyEarliestTimeText_Callback(app, evt);
                arrivalBodyEarliestTimeText_Callback(app, evt);
            end
        end

        % Menu selected function: flybyManSeq
        function flybyManSeq_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to flybyManSeq (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            hFig = flyByManeuverSequencer(handles.mainGUIFigure);

            openToolWindows = getappdata(handles.mainGUIFigure,'openToolWindows');
            openToolWindows = cleanOpenToolWindowsArr(openToolWindows);
            openToolWindows(end+1) = hFig;
            setappdata(handles.mainGUIFigure,'openToolWindows',openToolWindows);
        end

        % Menu selected function: getUTFromKSP
        function getUTFromKSP_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getUTFromKSP (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            secUT = readDoublesFromKSPTOTConnect('GetUT', '', true);
            if(secUT >= 0)
                set(gco, 'Value', num2str(secUT));
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                departBodyEarliestTimeText_Callback(app, evt);
                arrivalBodyEarliestTimeText_Callback(app, evt);
            end
        end

        % Menu selected function: gravParamMenu
        function gravParamMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to gravParamMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global options_gravParamType;

            if(strcmpi(options_gravParamType,'kspStockLike'))
                set(handles.useKspGmMenu,'Checked','on');
                set(handles.useRssLikeGmMenu,'Checked','off');
            elseif(strcmpi(options_gravParamType,'rssLike'))
                set(handles.useKspGmMenu,'Checked','off');
                set(handles.useRssLikeGmMenu,'Checked','on');
            end
        end

        % Menu selected function: launchVehicleDesignerMenu
        function launchVehicleDesignerMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to launchVehicleDesignerMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %                 mainGUIUserData = get(handles.mainGUIFigure, 'UserData');
            %                 celBodyData = mainGUIUserData{1,1};

            lvd_hFig = findall(0,'Tag','ma_LvdMainGUI');
            if(~isempty(lvd_hFig))
                figure(lvd_hFig);
            else
                hMsg = uiprogressdlg(app.mainGUIFigure, 'Message','Starting Launch Vehicle Designer.  Please wait...', ...
                                    'Title', 'Launch Vehicle Designer', ...
                                    'Indeterminate','on', ...
                                    'Icon','launchVehicleDesigner_20230323.png');

                try
                    lvdApp = ma_LvdMainGUI_App(app.celBodyData,handles.mainGUIFigure);

                    openToolWindows = getappdata(handles.mainGUIFigure,'openToolWindows');
                    openToolWindows = cleanOpenToolWindowsArr(openToolWindows);
                    openToolWindows(end+1) = lvdApp.ma_LvdMainGUI;
                    setappdata(handles.mainGUIFigure,'openToolWindows',openToolWindows);

                    if(isvalid(hMsg))
                        close(hMsg);
                    end
                catch
                    if(isvalid(hMsg))
                        close(hMsg);
                    end
                end
            end
        end

        % Menu selected function: loadBodiesFromFile
        function loadBodiesFromFile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            % hObject    handle to loadBodiesFromFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            mainGUIUserData = get(handles.mainGUIFigure, 'UserData');
            prePathName = mainGUIUserData{3,1};
            if(~isempty(prePathName))
                defaultName = [prePathName,'bodies.ini'];
            else
                defaultName = 'bodies.ini';
            end

            FilterSpec = {'*.ini','KSP TOT Bodies Info Files'};
            [FileName,PathName,~] = uigetfile(FilterSpec,'Select Bodies File to Load',defaultName);
            filePathName = [PathName,FileName];

            if(PathName == 0)
                return
            end
            [celBodyDataFromINI,~,~] = inifile(filePathName,'readall');
            app.celBodyData = processINIBodyInfo(celBodyDataFromINI, true, 'bodyInfo', app.mainGUIFigure);
            app.celBodyData = CelestialBodyData(app.celBodyData);

            [goodTF, celBodyWarnMsgs] = verifyCelBodyData(app.celBodyData);
            if(not(goodTF))
                msg = {sprintf('Potential issues were found with the loaded celestial body information: \n')};
                for(i=1:length(celBodyWarnMsgs))
                    msg{end+1} = sprintf('\t%s\n', celBodyWarnMsgs{i}); %#ok<AGROW>
                end

                msgbox(msg,'Celestial Body Data Warnings','warn');
            end

            updateAppOptions(hObject, 'ksptot', 'bodiesinifile', filePathName);

            mainGUIUserData = get(handles.mainGUIFigure, 'UserData');
            mainGUIUserData{1,1} = app.celBodyData;
            mainGUIUserData{3,1} = PathName;
            set(handles.mainGUIFigure, 'UserData', mainGUIUserData);

            cullArriveDepartCombosPerCB(handles.mainGUIFigure);
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            departBodyCombo_Callback(app, evt);
            arrivalBodyCombo_Callback(app, evt);
        end

        % Close request function: mainGUIFigure
        function mainGUIFigure_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to mainGUIFigure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: delete(hObject) closes the figure

            openToolWindows = getappdata(handles.mainGUIFigure,'openToolWindows');
            openToolWindows = cleanOpenToolWindowsArr(openToolWindows);
            valid = ishghandle(openToolWindows);
            if(any(valid))
                qstring = {'The following KSP TOT tools are currently open and will be closed upon closing the main KSP TOT window:',''};
                for(i=1:length(valid)) %#ok<*NO4LP>
                    if(valid(i))
                        qstring{end+1} = openToolWindows(i).Name; %#ok<AGROW>
                    end
                end
                button = questdlg(qstring,'Tool Windows Open','Okay','Cancel','Cancel');
            else
                button = 'Okay';
            end

            if(strcmpi(button,'Okay'))
                for(i=1:length(valid))
                    if(valid(i))
                        close(openToolWindows(i));
                        if(ishghandle(openToolWindows(i)))
                            delete(openToolWindows(i));
                        end
                    end
                end

                delete(hObject);
            else
                return;
            end
        end

        % Menu selected function: manExeAssist
        function manExeAssist_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to manExeAssist (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            hMsg = uiprogressdlg(app.mainGUIFigure, 'Message','Starting Maneuver Execution Assistant.  Please wait...', ...
                'Title', 'Maneuver Execution Assistant', ...
                'Indeterminate','on', ...
                'Icon', 'mea_20230327.png');

            meaApp = manExeAssistGUI_App(handles.mainGUIFigure);

            if(isvalid(hMsg))
                close(hMsg);
            end

            openToolWindows = getappdata(handles.mainGUIFigure,'openToolWindows');
            openToolWindows = cleanOpenToolWindowsArr(openToolWindows);
            openToolWindows(end+1) = meaApp.manExeAssistGUI;
            setappdata(handles.mainGUIFigure,'openToolWindows',openToolWindows);
        end

        % Menu selected function: mccRTS
        function mccRTS_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to mccRTS (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %                 mainGUIUserData = get(handles.mainGUIFigure, 'UserData');
            %                 celBodyData = mainGUIUserData{1,1};
            appOptions = getappdata(handles.mainGUIFigure,'appOptions');

            if(isprop(appOptions.ksptot,'rtshostname') && ~isempty(appOptions.ksptot.rtshostname))
                rtsHost = appOptions.ksptot.rtshostname;
            else
                rtsHost = 'localhost';
            end

            hFig = rts_mainGUI(app.celBodyData, rtsHost, handles.mainGUIFigure);

            openToolWindows = getappdata(handles.mainGUIFigure,'openToolWindows');
            openToolWindows = cleanOpenToolWindowsArr(openToolWindows);
            openToolWindows(end+1) = hFig;
            setappdata(handles.mainGUIFigure,'openToolWindows',openToolWindows);
        end

        % Menu selected function: missionArchitectMenu
        function missionArchitectMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to missionArchitectMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %                 mainGUIUserData = get(handles.mainGUIFigure, 'UserData');
            %                 celBodyData = mainGUIUserData{1,1};

            ma_hFig = findall(0,'Tag','ma_MainGUI');
            if(~isempty(ma_hFig))
                figure(ma_hFig);
            else
                hMsg = uiprogressdlg(app.mainGUIFigure, 'Message','Starting Mission Architect.  Please wait...', ...
                    'Title', 'Mission Architect', ...
                    'Indeterminate','on');

                hFig = ma_MainGUI(handles.mainGUIFigure, app.celBodyData);
                openToolWindows = getappdata(handles.mainGUIFigure,'openToolWindows');
                openToolWindows = cleanOpenToolWindowsArr(openToolWindows);
                openToolWindows(end+1) = hFig;
                setappdata(handles.mainGUIFigure,'openToolWindows',openToolWindows);

                if(isvalid(hMsg))
                    close(hMsg);
                end
            end
        end

        % Menu selected function: multiFlyByManeuverSequencerMenu
        function multiFlyByManeuverSequencerMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to multiFlyByManeuverSequencerMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %                 mainGUIUserData = get(handles.mainGUIFigure, 'UserData');
            %                 celBodyData = mainGUIUserData{1,1};

            %     hFig = multiFlyByManeuverSequencer(handles.mainGUIFigure);
            %                 hFig = multiFlyByManeuverSequencer(celBodyData);
            hMsg = uiprogressdlg(app.mainGUIFigure, 'Message','Starting Multi-flyby Maneuver Sequencer.  Please wait...', ...
                                'Title', 'Multi-flyby Maneuver Sequencer', ...
                                'Indeterminate','on', ...
                                'Icon','mfms_20230323.png');

            mfmsApp = multiFlyByManeuverSequencer_App(app.celBodyData);

            if(isvalid(hMsg))
                close(hMsg);
            end

            openToolWindows = getappdata(handles.mainGUIFigure,'openToolWindows');
            openToolWindows = cleanOpenToolWindowsArr(openToolWindows);
            openToolWindows(end+1) = mfmsApp.multiFlybyManSeqGUI;
            setappdata(handles.mainGUIFigure,'openToolWindows',openToolWindows);
        end

        % Menu selected function: multiFlybyPorkchopPlotterMenu
        function multiFlybyPorkchopPlotterMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to multiFlybyPorkchopPlotterMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            hMsg = uiprogressdlg(app.mainGUIFigure, 'Message','Starting Multi-flyby Porkchop Plotter.  Please wait...', ...
                'Title', 'Multi-flyby Porkchop Plotter', ...
                'Indeterminate','on');

            mfmsPorkApp = flyByPorkchopPlotterGUI_App(handles.mainGUIFigure);

            if(isvalid(hMsg))
                close(hMsg);
            end

            openToolWindows = getappdata(handles.mainGUIFigure,'openToolWindows');
            openToolWindows = cleanOpenToolWindowsArr(openToolWindows);
            openToolWindows(end+1) = mfmsPorkApp.flyByPorkchopPlotterGUI;
            setappdata(handles.mainGUIFigure,'openToolWindows',openToolWindows);
        end

        % Menu selected function: optimal2BurnOrbitChange
        function optimal2BurnOrbitChange_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to optimal2BurnOrbitChange (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            hMsg = uiprogressdlg(app.mainGUIFigure, 'Message','Starting Optimal 2-Burn Orbit Change.  Please wait...', ...
                'Title', 'Optimal 2-Burn Orbit Change', ...
                'Indeterminate','on', ...
                'Icon','o2boc_20230328.png');

            TBOCApp = OptimalTwoBurnOrbitChange_App(handles.mainGUIFigure);

            if(isvalid(hMsg))
                close(hMsg);
            end

            openToolWindows = getappdata(handles.mainGUIFigure,'openToolWindows');
            openToolWindows = cleanOpenToolWindowsArr(openToolWindows);
            openToolWindows(end+1) = TBOCApp.OptimalTwoBurnOrbitChangeGUI;
            setappdata(handles.mainGUIFigure,'openToolWindows',openToolWindows);
        end

        % Menu selected function: optionTimeSystemMenu
        function optionTimeSystemMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to optionTimeSystemMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global options_UseEarthTimeSystem;

            if(options_UseEarthTimeSystem == true)
                set(handles.useEarthTimeMenu,'Checked','on');
                set(handles.useKerbinTimeMenu,'Checked','off');
            else
                set(handles.useEarthTimeMenu,'Checked','off');
                set(handles.useKerbinTimeMenu,'Checked','on');
            end
        end

        % Menu selected function: optionsMenu
        function optionsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to optionsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %             [userData, varargout] = optionsGUI(handles.mainGUIFigure);
            output = AppDesignerGUIOutput({{},-1});
            optionsGUI_App(handles.mainGUIFigure, output);
            userData = output.output{1};
            flag = output.output{2};

            if(flag == -1)
                return;
            else
                set(handles.mainGUIFigure, 'UserData', userData);
            end
        end

        % Selection changed function: porkchopPlotTypeButtonGroup
        function porkchopPlotTypeButtonGroup_SelectionChangeFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to the selected object in porkchopPlotTypeButtonGroup
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            % handles    structure with handles and user data (see GUIDATA)
            plotPorkChopPlot(handles.mainGUIFigure);
        end

        % Menu selected function: recenterPlotAt
        function recenterPlotAt_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to recenterPlotAt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [departDayCenter,arriveDayCenter] = ginputax(handles.porkchopPlotAxes, 1);

            %                 userData = get(handles.mainGUIFigure,'UserData');
            %                 options = userData{1,9};

            %                 numSynPeriods = options.porkchopnumsynperiods;
            %                 celBodyData = userData{1,1};

            contents = cellstr(get(handles.centralBodyCombo,'String'));
            cbName = lower(contents{get(handles.centralBodyCombo,'Value')});
            cBodyInfo = app.celBodyData.(cbName);
            gmu = cBodyInfo.gm;

            contents = cellstr(get(handles.departBodyCombo,'String'));
            departBody = contents{get(handles.departBodyCombo,'Value')};

            contents = cellstr(get(handles.arrivalBodyCombo,'String'));
            arrivalBody = contents{get(handles.arrivalBodyCombo,'Value')};

            departBody = lower(departBody);
            departBodyInfo = app.celBodyData.(departBody);
            departBodyMeanMotion = computeMeanMotion(departBodyInfo.sma, gmu);

            arrivalBody = lower(arrivalBody);
            arrivalBodyInfo = app.celBodyData.(arrivalBody);
            arrivalBodyMeanMotion = computeMeanMotion(arrivalBodyInfo.sma, gmu);

            synPeriod = computeSynodicPeriod(departBodyMeanMotion, arrivalBodyMeanMotion);

            [~, ~, secInDay, ~] = getSecondsInVariousTimeUnits();

            %     hEarlyDepartTime = findobj(handles.mainGUIFigure,'Tag','departBodyEarliestTimeText');
            hEarlyDepartTime = handles.departBodyEarliestTimeText;
            earlyDepartTime = max((departDayCenter*(secInDay)) - synPeriod/2, 0);
            set(hEarlyDepartTime, 'String', num2str(earlyDepartTime));
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            departBodyEarliestTimeText_Callback(app, evt);

            %     hEarlyArrivalTime = findobj(handles.mainGUIFigure,'Tag','arrivalBodyEarliestTimeText');
            hEarlyArrivalTime = handles.arrivalBodyEarliestTimeText;
            earlyArrivalTime = max((arriveDayCenter*(secInDay)) - synPeriod/2, 0);
            set(hEarlyArrivalTime, 'String', num2str(earlyArrivalTime));
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            arrivalBodyEarliestTimeText_Callback(app, evt);

            generatePorkChopPlot(app.mainGUIFigure);
        end

        % Menu selected function: rendezvousManSeq
        function rendezvousManSeq_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to rendezvousManSeq (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %                 hFig = rendezvousGUI(handles.mainGUIFigure);
            hMsg = uiprogressdlg(app.mainGUIFigure, 'Message','Starting Rendezvous Maneuver Sequencer.  Please wait...', ...
                'Title', 'Rendezvous Maneuver Sequencer', ...
                'Indeterminate','on', ...
                'Icon', 'rms_20230327.png');

            rApp = rendezvousGUI_App(handles.mainGUIFigure);

            if(isvalid(hMsg))
                close(hMsg);
            end

            openToolWindows = getappdata(handles.mainGUIFigure,'openToolWindows');
            openToolWindows = cleanOpenToolWindowsArr(openToolWindows);
            openToolWindows(end+1) = rApp.rendezvousGUI;
            setappdata(handles.mainGUIFigure,'openToolWindows',openToolWindows);
        end

        % Menu selected function: resetStatusBox
        function resetStatusBox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to resetStatusBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % hStatusBox = findobj(handles.mainGUIFigure,'Tag','statusText');
            set(handles.statusText, 'String', setINIStatusBoxMsg());
        end

        % Menu selected function: rtsUploadManeuver
        function rtsUploadManeuver_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to rtsUploadManeuver (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            uploadManeuverToKSP_App();

            %             openToolWindows = getappdata(handles.mainGUIFigure,'openToolWindows');
            %             openToolWindows = cleanOpenToolWindowsArr(openToolWindows);
            %             openToolWindows(end+1) = hFig;
            %             setappdata(handles.mainGUIFigure,'openToolWindows',openToolWindows);
        end

        % Button pushed function: testPorkChopper
        function testPorkChopper_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            %             hObject = app.testPorkChopper;

            % hObject    handle to testPorkChopper (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            errMsg = {};

            departBodyEarliestTime = str2double(get(handles.departBodyEarliestTimeText,'String'));
            enteredStr = get(handles.departBodyEarliestTimeText,'String');
            numberName = 'Earliest departure time';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(departBodyEarliestTime, numberName, lb, ub, isInt, errMsg, enteredStr);

            arriveBodyEarliestTime = str2double(get(handles.arrivalBodyEarliestTimeText,'String'));
            enteredStr = get(handles.arrivalBodyEarliestTimeText,'String');
            numberName = 'Earliest arrival time';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(arriveBodyEarliestTime, numberName, lb, ub, isInt, errMsg, enteredStr);

            contents = cellstr(get(handles.departBodyCombo,'String'));
            departBody = contents{get(handles.departBodyCombo,'Value')};
            contents = cellstr(get(handles.arrivalBodyCombo,'String'));
            arriveBody = contents{get(handles.arrivalBodyCombo,'Value')};
            errMsg = checkIfInputBodiesAreSame(departBody, arriveBody, errMsg);

            if(isempty(errMsg))
                generatePorkChopPlot(handles.mainGUIFigure);
                set(handles.computeDepartBurn, 'Enable', 'On');
                app.porkchopPlotAxes.Colormap = colormap('parula');

                applySelectedThemeToApp(app);

                refresh(handles.mainGUIFigure);
                try

                catch ME
                    errordlg(ME.message);
                    throw(ME);
                end
            else
                msgbox(errMsg,'Errors were found in porkchop plot inputs','error');
            end
        end

        % Menu selected function: useEarthTimeMenu
        function useEarthTimeMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to useEarthTimeMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global options_UseEarthTimeSystem;

            options_UseEarthTimeSystem = true;
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            departBodyEarliestTimeText_Callback(app, evt);
            arrivalBodyEarliestTimeText_Callback(app, evt);
            departBodyCombo_Callback(app, evt);

            updateAppOptions(handles.mainGUIFigure, 'ksptot', 'timesystem', 'Earth');

            appOptions = getappdata(handles.mainGUIFigure,'appOptions');
            notify(appOptions.ksptot,'TimeSystemUpdated');

            try
                testPorkChopper_Callback(app, evt);
            catch ME %#ok<NASGU>
                return;
            end
        end

        % Menu selected function: useKerbinTimeMenu
        function useKerbinTimeMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to useKerbinTimeMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global options_UseEarthTimeSystem;

            options_UseEarthTimeSystem = false;
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            departBodyEarliestTimeText_Callback(app, evt);
            arrivalBodyEarliestTimeText_Callback(app, evt);
            departBodyCombo_Callback(app, evt);

            updateAppOptions(handles.mainGUIFigure, 'ksptot', 'timesystem', 'Kerbin');

            appOptions = getappdata(handles.mainGUIFigure,'appOptions');
            notify(appOptions.ksptot,'TimeSystemUpdated');

            try
                testPorkChopper_Callback(app, evt);
            catch(ME) %#ok<SEPEX,NOPRT>
                return;
            end
        end

        % Menu selected function: useKspGmMenu
        function useKspGmMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to useKspGmMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global options_gravParamType;

            options_gravParamType = 'kspStockLike';
            updateAppOptions(handles.mainGUIFigure, 'ksptot', 'gravParamType', 'kspStockLike');

            appOptions = getappdata(handles.mainGUIFigure,'appOptions');
            notify(appOptions.ksptot,'GravParamTypeUpdated');
        end

        % Menu selected function: useRssLikeGmMenu
        function useRssLikeGmMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to useRssLikeGmMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global options_gravParamType;

            options_gravParamType = 'rssLike';
            updateAppOptions(handles.mainGUIFigure, 'ksptot', 'gravParamType', 'rssLike');

            appOptions = getappdata(handles.mainGUIFigure,'appOptions');
            notify(appOptions.ksptot,'GravParamTypeUpdated');
        end

        % Menu selected function: vehicleSizingToolMenu
        function vehicleSizingToolMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to vehicleSizingToolMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %                 mainGUIUserData = get(handles.mainGUIFigure, 'UserData');
            %                 celBodyData = mainGUIUserData{1,1};

            vs_hFig = findall(0,'Tag','vs_mainGUI');
            if(~isempty(vs_hFig))
                figure(vs_hFig);
            else
                hMsg = uiprogressdlg(app.mainGUIFigure, 'Message','Starting Starting Vehicle Sizing Tool.  Please wait...', ...
                                    'Title', 'Vehicle Sizing Tool', ...
                                    'Indeterminate','on', ...
                                    'Icon', 'vst_20230324.png');

                vsApp = vs_mainGUI_App(app.celBodyData,handles.mainGUIFigure);

                openToolWindows = getappdata(handles.mainGUIFigure,'openToolWindows');
                openToolWindows = cleanOpenToolWindowsArr(openToolWindows);
                openToolWindows(end+1) = vsApp.vs_mainGUI;
                setappdata(handles.mainGUIFigure,'openToolWindows',openToolWindows);

                if(isvalid(hMsg))
                    close(hMsg);
                end
            end
        end

        % Callback function: uitoggletool4
        function uitoggletool4_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable data tips based on the
            % tool's current state.
            state = app.uitoggletool4.State;
            datacursormode(app.mainGUIFigure, char(state));
        end

        % Callback function: uitoggletool3
        function uitoggletool3_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.uitoggletool3.State;
            zoomModeObject = zoom(app.mainGUIFigure);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Callback function: uitoggletool2
        function uitoggletool2_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.uitoggletool2.State;
            zoomModeObject = zoom(app.mainGUIFigure);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Callback function: uitoggletool1
        function uitoggletool1_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool1.State;
            pan(app.mainGUIFigure, char(state));
        end

        % Menu selected function: ConfigureCelestialBodyStateCacheMenu
        function ConfigureCelestialBodyStateCacheMenuSelected(app, event)
            configureCelBodyStateCacheGUI_App(app.celBodyData);
        end

        % Menu selected function: CelestialBodyCatalogMenu
        function CelestialBodyCatalogMenuSelected(app, event)
            ma_CelBodyCatalogGUI_App(app.celBodyData);
        end

        % Menu selected function: EditThemesMenu
        function EditThemesMenuSelected(app, event)
            AppThemeEditorGUI_App(app.appThemer);
        end

        % Menu selected function: ViewMenu
        function ViewMenuSelected(app, event)
            themes = app.appThemer.themes;
            delete(app.SetThemeMenu.Children);

            if(numel(themes) > 0)
                for(i=1:numel(themes))
                    theme = themes(i);

                    menuName = sprintf('%s', theme.name);

                    callback = @(src,evt) setThemeMenuCallback(app, src, evt, theme);
                    uimenu(app.SetThemeMenu, 'Text',menuName, 'MenuSelectedFcn', callback);
                end

            else
                uimenu(handles.openRecentMissionPlansMenu, 'Text','No Recent Missions', 'Enable','off');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainGUIFigure = uifigure('Visible', 'off');
            colormap(app.mainGUIFigure, 'parula');
            app.mainGUIFigure.Name = 'KSP Trajectory Optimization Tool';
            app.mainGUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.mainGUIFigure.CloseRequestFcn = createCallbackFcn(app, @mainGUIFigure_CloseRequestFcn, true);
            app.mainGUIFigure.Tag = 'mainGUIFigure';
            app.fileMenu = uimenu(app.mainGUIFigure);
            app.fileMenu.Text = 'File';
            app.fileMenu.Tag = 'fileMenu';
            app.loadBodiesFromFile = uimenu(app.fileMenu);
            app.loadBodiesFromFile.MenuSelectedFcn = createCallbackFcn(app, @loadBodiesFromFile_Callback, true);
            app.loadBodiesFromFile.Text = 'Load Bodies From File';
            app.loadBodiesFromFile.Tag = 'loadBodiesFromFile';
            app.createNewBodiesFileFromKSP = uimenu(app.fileMenu);
            app.createNewBodiesFileFromKSP.MenuSelectedFcn = createCallbackFcn(app, @createNewBodiesFileFromKSP_Callback, true);
            app.createNewBodiesFileFromKSP.Text = 'Create New Bodies File from KSP';
            app.createNewBodiesFileFromKSP.Tag = 'createNewBodiesFileFromKSP';
            app.edit = uimenu(app.mainGUIFigure);
            app.edit.Text = 'Edit';
            app.edit.Tag = 'edit';
            app.optionsMenu = uimenu(app.edit);
            app.optionsMenu.MenuSelectedFcn = createCallbackFcn(app, @optionsMenu_Callback, true);
            app.optionsMenu.Text = 'Options';
            app.optionsMenu.Tag = 'optionsMenu';
            app.optionTimeSystemMenu = uimenu(app.edit);
            app.optionTimeSystemMenu.MenuSelectedFcn = createCallbackFcn(app, @optionTimeSystemMenu_Callback, true);
            app.optionTimeSystemMenu.Text = 'Time System';
            app.optionTimeSystemMenu.Tag = 'optionTimeSystemMenu';
            app.useEarthTimeMenu = uimenu(app.optionTimeSystemMenu);
            app.useEarthTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @useEarthTimeMenu_Callback, true);
            app.useEarthTimeMenu.Checked = 'on';
            app.useEarthTimeMenu.Text = 'Use Earth Time';
            app.useEarthTimeMenu.Tag = 'useEarthTimeMenu';
            app.useKerbinTimeMenu = uimenu(app.optionTimeSystemMenu);
            app.useKerbinTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @useKerbinTimeMenu_Callback, true);
            app.useKerbinTimeMenu.Text = 'Use Kerbin Time';
            app.useKerbinTimeMenu.Tag = 'useKerbinTimeMenu';
            app.gravParamMenu = uimenu(app.edit);
            app.gravParamMenu.MenuSelectedFcn = createCallbackFcn(app, @gravParamMenu_Callback, true);
            app.gravParamMenu.Text = 'Gravitational Parameter';
            app.gravParamMenu.Tag = 'gravParamMenu';
            app.useKspGmMenu = uimenu(app.gravParamMenu);
            app.useKspGmMenu.MenuSelectedFcn = createCallbackFcn(app, @useKspGmMenu_Callback, true);
            app.useKspGmMenu.Checked = 'on';
            app.useKspGmMenu.Text = 'Use KSP-like GM';
            app.useKspGmMenu.Tag = 'useKspGmMenu';
            app.useRssLikeGmMenu = uimenu(app.gravParamMenu);
            app.useRssLikeGmMenu.MenuSelectedFcn = createCallbackFcn(app, @useRssLikeGmMenu_Callback, true);
            app.useRssLikeGmMenu.Text = 'Use RSS-like G(M+m)';
            app.useRssLikeGmMenu.Tag = 'useRssLikeGmMenu';
            app.ConfigureCelestialBodyStateCacheMenu = uimenu(app.edit);
            app.ConfigureCelestialBodyStateCacheMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfigureCelestialBodyStateCacheMenuSelected, true);
            app.ConfigureCelestialBodyStateCacheMenu.Separator = 'on';
            app.ConfigureCelestialBodyStateCacheMenu.Text = 'Configure Celestial Body State Cache';
            app.ViewMenu = uimenu(app.mainGUIFigure);
            app.ViewMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.ViewMenu.Text = 'View';
            app.EditThemesMenu = uimenu(app.ViewMenu);
            app.EditThemesMenu.MenuSelectedFcn = createCallbackFcn(app, @EditThemesMenuSelected, true);
            app.EditThemesMenu.Tooltip = {'Allows users to change the colors and general apperance of all KSPTOT UI windows globally.'};
            app.EditThemesMenu.Text = 'Edit Themes';
            app.SetThemeMenu = uimenu(app.ViewMenu);
            app.SetThemeMenu.Text = 'Set Theme';
            app.toolsMenu = uimenu(app.mainGUIFigure);
            app.toolsMenu.Text = 'Tools';
            app.toolsMenu.Tag = 'toolsMenu';
            app.missionPlanningMenu = uimenu(app.toolsMenu);
            app.missionPlanningMenu.Text = 'Mission Planning';
            app.missionPlanningMenu.Tag = 'missionPlanningMenu';
            app.missionArchitectMenu = uimenu(app.missionPlanningMenu);
            app.missionArchitectMenu.MenuSelectedFcn = createCallbackFcn(app, @missionArchitectMenu_Callback, true);
            app.missionArchitectMenu.Text = 'Mission Architect';
            app.missionArchitectMenu.Tag = 'missionArchitectMenu';
            app.launchVehicleDesignerMenu = uimenu(app.missionPlanningMenu);
            app.launchVehicleDesignerMenu.MenuSelectedFcn = createCallbackFcn(app, @launchVehicleDesignerMenu_Callback, true);
            app.launchVehicleDesignerMenu.Text = 'Launch Vehicle Designer';
            app.launchVehicleDesignerMenu.Tag = 'launchVehicleDesignerMenu';
            app.maneuverPlanningMenu = uimenu(app.toolsMenu);
            app.maneuverPlanningMenu.Text = 'Maneuver Planning';
            app.maneuverPlanningMenu.Tag = 'maneuverPlanningMenu';
            app.flybyManSeq = uimenu(app.maneuverPlanningMenu);
            app.flybyManSeq.MenuSelectedFcn = createCallbackFcn(app, @flybyManSeq_Callback, true);
            app.flybyManSeq.Enable = 'off';
            app.flybyManSeq.Text = 'Flyby Maneuver Sequencer';
            app.flybyManSeq.Tag = 'flybyManSeq';
            app.multiFlyByManeuverSequencerMenu = uimenu(app.maneuverPlanningMenu);
            app.multiFlyByManeuverSequencerMenu.MenuSelectedFcn = createCallbackFcn(app, @multiFlyByManeuverSequencerMenu_Callback, true);
            app.multiFlyByManeuverSequencerMenu.Text = 'Multi-Flyby Maneuver Sequencer';
            app.multiFlyByManeuverSequencerMenu.Tag = 'multiFlyByManeuverSequencerMenu';
            app.multiFlybyPorkchopPlotterMenu = uimenu(app.maneuverPlanningMenu);
            app.multiFlybyPorkchopPlotterMenu.MenuSelectedFcn = createCallbackFcn(app, @multiFlybyPorkchopPlotterMenu_Callback, true);
            app.multiFlybyPorkchopPlotterMenu.Text = 'Multi-Flyby Porkchop Plotter';
            app.multiFlybyPorkchopPlotterMenu.Tag = 'multiFlybyPorkchopPlotterMenu';
            app.rendezvousManSeq = uimenu(app.maneuverPlanningMenu);
            app.rendezvousManSeq.MenuSelectedFcn = createCallbackFcn(app, @rendezvousManSeq_Callback, true);
            app.rendezvousManSeq.Text = 'Rendezvous Maneuver Sequencer';
            app.rendezvousManSeq.Tag = 'rendezvousManSeq';
            app.optimal2BurnOrbitChange = uimenu(app.maneuverPlanningMenu);
            app.optimal2BurnOrbitChange.MenuSelectedFcn = createCallbackFcn(app, @optimal2BurnOrbitChange_Callback, true);
            app.optimal2BurnOrbitChange.Text = 'Optimal 2-Burn Orbit Change';
            app.optimal2BurnOrbitChange.Tag = 'optimal2BurnOrbitChange';
            app.manExeAssist = uimenu(app.maneuverPlanningMenu);
            app.manExeAssist.MenuSelectedFcn = createCallbackFcn(app, @manExeAssist_Callback, true);
            app.manExeAssist.Text = 'Maneuver Execution Assistant';
            app.manExeAssist.Tag = 'manExeAssist';
            app.vehicleSizingMenu = uimenu(app.toolsMenu);
            app.vehicleSizingMenu.Text = 'Vehicle Sizing';
            app.vehicleSizingMenu.Tag = 'vehicleSizingMenu';
            app.vehicleSizingToolMenu = uimenu(app.vehicleSizingMenu);
            app.vehicleSizingToolMenu.MenuSelectedFcn = createCallbackFcn(app, @vehicleSizingToolMenu_Callback, true);
            app.vehicleSizingToolMenu.Text = 'Vehicle Sizing Tool';
            app.vehicleSizingToolMenu.Tag = 'vehicleSizingToolMenu';
            app.CelestialBodyCatalogMenu = uimenu(app.toolsMenu);
            app.CelestialBodyCatalogMenu.MenuSelectedFcn = createCallbackFcn(app, @CelestialBodyCatalogMenuSelected, true);
            app.CelestialBodyCatalogMenu.Separator = 'on';
            app.CelestialBodyCatalogMenu.Text = 'Celestial Body Catalog';
            app.Untitled_1 = uimenu(app.mainGUIFigure);
            app.Untitled_1.Text = 'KSP Real Time System';
            app.Untitled_1.Tag = 'Untitled_1';
            app.rtsUploadManeuver = uimenu(app.Untitled_1);
            app.rtsUploadManeuver.MenuSelectedFcn = createCallbackFcn(app, @rtsUploadManeuver_Callback, true);
            app.rtsUploadManeuver.Text = 'Upload Maneuver';
            app.rtsUploadManeuver.Tag = 'rtsUploadManeuver';
            app.mccRTS = uimenu(app.Untitled_1);
            app.mccRTS.MenuSelectedFcn = createCallbackFcn(app, @mccRTS_Callback, true);
            app.mccRTS.Text = 'MCC Real Time System';
            app.mccRTS.Tag = 'mccRTS';
            app.helpMenu = uimenu(app.mainGUIFigure);
            app.helpMenu.Text = 'Help';
            app.helpMenu.Tag = 'helpMenu';
            app.aboutMenu = uimenu(app.helpMenu);
            app.aboutMenu.MenuSelectedFcn = createCallbackFcn(app, @aboutMenu_Callback, true);
            app.aboutMenu.Text = 'About';
            app.aboutMenu.Tag = 'aboutMenu';
            app.uitoolbar1 = uitoolbar(app.mainGUIFigure);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uitoggletool1 = uitoggletool(app.uitoolbar1);
            app.uitoggletool1.Tag = 'uitoggletool1';
            app.uitoggletool1.Tooltip = 'Pan';
            app.uitoggletool1.ClickedCallback = createCallbackFcn(app, @uitoggletool1_ClickedCallback, true);
            app.uitoggletool1.Icon = 'pan_image.png';
            app.uitoggletool2 = uitoggletool(app.uitoolbar1);
            app.uitoggletool2.Tag = 'uitoggletool2';
            app.uitoggletool2.Tooltip = 'Zoom Out';
            app.uitoggletool2.ClickedCallback = createCallbackFcn(app, @uitoggletool2_ClickedCallback, true);
            app.uitoggletool2.Icon = 'zoom-out.png';
            app.uitoggletool3 = uitoggletool(app.uitoolbar1);
            app.uitoggletool3.Tag = 'uitoggletool3';
            app.uitoggletool3.Tooltip = 'Zoom In';
            app.uitoggletool3.ClickedCallback = createCallbackFcn(app, @uitoggletool3_ClickedCallback, true);
            app.uitoggletool3.Icon = 'zoom-in(1).png';
            app.uitoggletool4 = uitoggletool(app.uitoolbar1);
            app.uitoggletool4.Tag = 'uitoggletool4';
            app.uitoggletool4.Tooltip = 'Data Cursor';
            app.uitoggletool4.ClickedCallback = createCallbackFcn(app, @uitoggletool4_ClickedCallback, true);
            app.uitoggletool4.Icon = 'line-chart-data-label.png';
            app.GridLayout = uigridlayout(app.mainGUIFigure);
            app.statusText = uitextarea(app.GridLayout);
            app.statusText.Tag = 'statusText';
            app.statusText.Tooltip = 'Right-click to clear status window';
            app.statusText.Value = {'StatusBox'; 'StatusBox'; 'StatusBox'; 'StatusBox'; 'StatusBox'; 'StatusBox'};
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.porkchopPlotTypeButtonGroup = uibuttongroup(app.GridLayout2);
            app.porkchopPlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @porkchopPlotTypeButtonGroup_SelectionChangeFcn, true);
            app.porkchopPlotTypeButtonGroup.Title = 'Plot Data Type';
            app.porkchopPlotTypeButtonGroup.Tag = 'porkchopPlotTypeButtonGroup';
            app.departDeltaVRadioButton = uiradiobutton(app.porkchopPlotTypeButtonGroup);
            app.departDeltaVRadioButton.Tag = 'departDeltaVRadioButton';
            app.departDeltaVRadioButton.Text = 'Departure Delta-V';
            app.arrivalDeltaVRadioButton = uiradiobutton(app.porkchopPlotTypeButtonGroup);
            app.arrivalDeltaVRadioButton.Tag = 'arrivalDeltaVRadioButton';
            app.arrivalDeltaVRadioButton.Text = 'Arrival Delta-V';
            app.departArrivalDeltaVRadioButton = uiradiobutton(app.porkchopPlotTypeButtonGroup);
            app.departArrivalDeltaVRadioButton.Tag = 'departArrivalDeltaVRadioButton';
            app.departArrivalDeltaVRadioButton.Text = 'Departure + Arrival Delta-V';
            app.departArrivalDeltaVRadioButton.Value = true;
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.synPeriodLabelText = uilabel(app.GridLayout4);
            app.synPeriodLabelText.Tag = 'synPeriodLabelText';
            app.synPeriodLabelText.Tooltip = 'The synodic period is the period after which two bodies are geometrically in the same positions (assuming circular orbits)';
            app.synPeriodLabelText.Text = 'SynPeriod';
            app.TransferCentralBodyPanel = uipanel(app.GridLayout4);
            app.TransferCentralBodyPanel.Title = 'Transfer Central Body';
            app.GridLayout5 = uigridlayout(app.TransferCentralBodyPanel);
            app.centralBodyCombo = uidropdown(app.GridLayout5);
            app.centralBodyCombo.Items = {'Pop-up Menu'};
            app.centralBodyCombo.ValueChangedFcn = createCallbackFcn(app, @centralBodyCombo_Callback, true);
            app.centralBodyCombo.Tag = 'centralBodyCombo';
            app.centralBodyCombo.Tooltip = 'The selection in this menu dictates the transfer orbit central body across the application.';
            app.centralBodyCombo.Value = 'Pop-up Menu';
            app.DepartureParametersPanel = uipanel(app.GridLayout4);
            app.DepartureParametersPanel.Title = 'Departure Parameters';
            app.GridLayout6 = uigridlayout(app.DepartureParametersPanel);
            app.text4 = uilabel(app.GridLayout6);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Earliest Departure Time';
            app.departBodyCombo = uidropdown(app.GridLayout6);
            app.departBodyCombo.Items = {'Pop-up Menu'};
            app.departBodyCombo.ValueChangedFcn = createCallbackFcn(app, @departBodyCombo_Callback, true);
            app.departBodyCombo.Tag = 'departBodyCombo';
            app.departBodyCombo.Tooltip = 'The body to depart from';
            app.departBodyCombo.Value = 'Pop-up Menu';
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.text7 = uilabel(app.GridLayout7);
            app.text7.Tag = 'text7';
            app.text7.Text = 'sec';
            app.departBodyEarliestTimeText = uieditfield(app.GridLayout7, 'text');
            app.departBodyEarliestTimeText.ValueChangedFcn = createCallbackFcn(app, @departBodyEarliestTimeText_Callback, true);
            app.departBodyEarliestTimeText.Tag = 'departBodyEarliestTimeText';
            app.departBodyEarliestTimeText.Tooltip = 'Seconds from game start (right-click to enter as date)';
            app.departBodyEarliestTimeText.Value = '0.0';
            app.text3 = uilabel(app.GridLayout6);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Departure Body';
            app.departDateText = uilabel(app.GridLayout6);
            app.departDateText.Tag = 'departDateText';
            app.departDateText.Text = 'Date';
            app.ArrivalParametersPanel = uipanel(app.GridLayout4);
            app.ArrivalParametersPanel.Title = 'Arrival Parameters';
            app.GridLayout8 = uigridlayout(app.ArrivalParametersPanel);
            app.text5 = uilabel(app.GridLayout8);
            app.text5.Tag = 'text3';
            app.text5.Text = 'Arrival Body';
            app.text6 = uilabel(app.GridLayout8);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Earliest Arrival Time';
            app.arriveDateText = uilabel(app.GridLayout8);
            app.arriveDateText.Tag = 'arriveDateText';
            app.arriveDateText.Text = 'Date';
            app.arrivalBodyCombo = uidropdown(app.GridLayout8);
            app.arrivalBodyCombo.Items = {'Pop-up Menu'};
            app.arrivalBodyCombo.ValueChangedFcn = createCallbackFcn(app, @arrivalBodyCombo_Callback, true);
            app.arrivalBodyCombo.Tag = 'arrivalBodyCombo';
            app.arrivalBodyCombo.Tooltip = 'The body to arrive at';
            app.arrivalBodyCombo.Value = 'Pop-up Menu';
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.text8 = uilabel(app.GridLayout9);
            app.text8.Tag = 'text8';
            app.text8.Text = 'sec';
            app.arrivalBodyEarliestTimeText = uieditfield(app.GridLayout9, 'text');
            app.arrivalBodyEarliestTimeText.ValueChangedFcn = createCallbackFcn(app, @arrivalBodyEarliestTimeText_Callback, true);
            app.arrivalBodyEarliestTimeText.Tag = 'arrivalBodyEarliestTimeText';
            app.arrivalBodyEarliestTimeText.Tooltip = 'Seconds from game start (right-click to enter as date)';
            app.arrivalBodyEarliestTimeText.Value = '0.0';
            app.Panel = uipanel(app.GridLayout2);
            app.GridLayout10 = uigridlayout(app.Panel);
            app.porkchopPlotAxes = uiaxes(app.GridLayout10);
            app.porkchopPlotAxes.Tag = 'porkchopPlotAxes';
            app.Panel_3 = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel_3);
            app.computeDepartBurn = uibutton(app.GridLayout3, 'push');
            app.computeDepartBurn.ButtonPushedFcn = createCallbackFcn(app, @computeDepartBurn_Callback, true);
            app.computeDepartBurn.Tag = 'computeDepartBurn';
            app.computeDepartBurn.Enable = 'off';
            app.computeDepartBurn.Tooltip = 'Brings up the Compute Departure application, which assists in planning ballastic trajectories between two bodies.';
            app.computeDepartBurn.Text = 'Compute Departure Burn';
            app.testPorkChopper = uibutton(app.GridLayout3, 'push');
            app.testPorkChopper.ButtonPushedFcn = createCallbackFcn(app, @testPorkChopper_Callback, true);
            app.testPorkChopper.Tag = 'testPorkChopper';
            app.testPorkChopper.Tooltip = 'Computes the porkchop plot data.  A valid porkchop must exist to compute a departure burn';
            app.testPorkChopper.Text = 'Compute Porkchop Plot';
            app.enterUTAsDateTime = uicontextmenu(app.mainGUIFigure);
            app.enterUTAsDateTime.Tag = 'enterUTAsDateTime';
            app.enterUTAsDateTime_1 = uimenu(app.enterUTAsDateTime);
            app.enterUTAsDateTime_1.MenuSelectedFcn = createCallbackFcn(app, @enterUTAsDateTime_Callback, true);
            app.enterUTAsDateTime_1.Text = 'Enter UT As Date/Time';
            app.enterUTAsDateTime_1.Tag = 'enterUTAsDateTime_1';
            app.getUTFromKSP = uimenu(app.enterUTAsDateTime);
            app.getUTFromKSP.MenuSelectedFcn = createCallbackFcn(app, @getUTFromKSP_Callback, true);
            app.getUTFromKSP.Text = 'Get UT From KSP';
            app.getUTFromKSP.Tag = 'getUTFromKSP';
            % Assign app.enterUTAsDateTime
            app.departBodyEarliestTimeText.ContextMenu = app.enterUTAsDateTime;
            app.arrivalBodyEarliestTimeText.ContextMenu = app.enterUTAsDateTime;
            app.statusBoxMenu = uicontextmenu(app.mainGUIFigure);
            app.statusBoxMenu.Tag = 'statusBoxMenu';
            app.resetStatusBox = uimenu(app.statusBoxMenu);
            app.resetStatusBox.MenuSelectedFcn = createCallbackFcn(app, @resetStatusBox_Callback, true);
            app.resetStatusBox.Text = 'Reset Status Box';
            app.resetStatusBox.Tag = 'resetStatusBox';
            % Assign app.statusBoxMenu
            app.statusText.ContextMenu = app.statusBoxMenu;
            app.porkchopPlotAxesMenu = uicontextmenu(app.mainGUIFigure);
            app.porkchopPlotAxesMenu.Tag = 'porkchopPlotAxesMenu';
            app.recenterPlotAt = uimenu(app.porkchopPlotAxesMenu);
            app.recenterPlotAt.MenuSelectedFcn = createCallbackFcn(app, @recenterPlotAt_Callback, true);
            app.recenterPlotAt.Text = 'Re-Center Plot At...';
            app.recenterPlotAt.Tag = 'recenterPlotAt';
            % Show the figure after all components are created
            app.mainGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.mainGUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)mainGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.mainGUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainGUIFigure)
        end
    end
end