classdef manExeAssistGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        manExeAssistGUI              matlab.ui.Figure
        uitoolbar1                   matlab.ui.container.Toolbar
        uitoggletool1                matlab.ui.container.toolbar.ToggleTool
        uitoggletool2                matlab.ui.container.toolbar.ToggleTool
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout3                  matlab.ui.container.GridLayout
        GridLayout6                  matlab.ui.container.GridLayout
        computeBurnTimingInfoButton  matlab.ui.control.Button
        dvManInfoText                matlab.ui.control.TextArea
        text29                       matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        orbitDispAxes                matlab.ui.control.UIAxes
        GridLayout4                  matlab.ui.container.GridLayout
        uipanel4                     matlab.ui.container.Panel
        GridLayout10                 matlab.ui.container.GridLayout
        text27                       matlab.ui.control.Label
        text25                       matlab.ui.control.Label
        text23                       matlab.ui.control.Label
        iniMassText                  matlab.ui.control.EditField
        ispText                      matlab.ui.control.EditField
        thrustText                   matlab.ui.control.EditField
        text28                       matlab.ui.control.Label
        text26                       matlab.ui.control.Label
        text24                       matlab.ui.control.Label
        uipanel3                     matlab.ui.container.Panel
        GridLayout9                  matlab.ui.container.GridLayout
        text21                       matlab.ui.control.Label
        text19                       matlab.ui.control.Label
        text17                       matlab.ui.control.Label
        text15                       matlab.ui.control.Label
        burnTruText                  matlab.ui.control.EditField
        normalDVText                 matlab.ui.control.EditField
        radialDVText                 matlab.ui.control.EditField
        progradeDVText               matlab.ui.control.EditField
        text22                       matlab.ui.control.Label
        text20                       matlab.ui.control.Label
        text18                       matlab.ui.control.Label
        text16                       matlab.ui.control.Label
        uipanel1                     matlab.ui.container.Panel
        GridLayout8                  matlab.ui.container.GridLayout
        text4                        matlab.ui.control.Label
        text3                        matlab.ui.control.Label
        text2                        matlab.ui.control.Label
        text1                        matlab.ui.control.Label
        eArgText                     matlab.ui.control.EditField
        eRAANText                    matlab.ui.control.EditField
        eIncText                     matlab.ui.control.EditField
        eEccText                     matlab.ui.control.EditField
        eSMAText                     matlab.ui.control.EditField
        text9                        matlab.ui.control.Label
        text8                        matlab.ui.control.Label
        text7                        matlab.ui.control.Label
        text6                        matlab.ui.control.Label
        text5                        matlab.ui.control.Label
        uipanel2                     matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        orbitingAboutCombo           matlab.ui.control.DropDown
        text10                       matlab.ui.control.Label
        GridLayout2                  matlab.ui.container.GridLayout
        Image                        matlab.ui.control.Image
        titleText                    matlab.ui.control.Label
        orbitContextMenu             matlab.ui.container.ContextMenu
        getOrbitFromSFSFile          matlab.ui.container.Menu
        getOrbitFromKSPMenu          matlab.ui.container.Menu
        getOrbitFromKSPActiveVesselMenu  matlab.ui.container.Menu
        copyOrbitToClipboardMenu     matlab.ui.container.Menu
        pasteOrbitFromClipboardMenu  matlab.ui.container.Menu
        sysLevelProps                matlab.ui.container.ContextMenu
        computeSysLvlProps           matlab.ui.container.Menu
        burnParamsContextMenu        matlab.ui.container.ContextMenu
        getBurnParamsFromKspActiveVesselMenu  matlab.ui.container.Menu
    end

    
    properties (Access = private)
        celBodyData % Description
    end

    methods (Access = private)
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.

            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool1, app.uitoggletool2];
            interactiveTools(event.Source == interactiveTools) = [];

            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');

            % Set figure interactions to 'off'.
            datacursormode(app.manExeAssistGUI, 'off')
            rotate3d(app.manExeAssistGUI, 'off');
            pan(app.manExeAssistGUI, 'off');
            zoom(app.manExeAssistGUI,'off');
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function manExeAssistGUI_OpeningFcn(app, celBodyData)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to manExeAssistGUI (see VARARGIN)

            % Choose default command line output for manExeAssistGUI
            handles.output = hObject;
            centerUIFigure(app.manExeAssistGUI);
            applySelectedThemeToApp(app);

            % Update handles structure
            guidata(hObject, handles);
            app.celBodyData = celBodyData;

            %Possible fix for people with display issues.
            %             checkForCharUnitsInGUI(hObject);

            % userData = cell(2);
            % mainGUIHandle = varargin{1};
            % userData{1,1} = mainGUIHandle;
            % userData{2,1} = [];
            % set(hObject, 'UserData', userData);
            % mainUserData = get(mainGUIHandle, 'UserData');
            % celBodyData = mainUserData{1,1};

            % [~, arriveDepartNames] = genCBArriveDepartComboBoxStrs(mainGUIHandle);
            allNames = ma_getSortedBodyNames(app.celBodyData);
            departArriveComboString = char(cap1stLetter(allNames));
            set(handles.orbitingAboutCombo,'String',departArriveComboString);
            set(handles.orbitingAboutCombo, 'Value', 5);

            hold on;
            axes(app.orbitDispAxes);
            set(app.orbitDispAxes,'XTickLabel',[]);
            set(app.orbitDispAxes,'YTickLabel',[]);
            set(app.orbitDispAxes,'ZTickLabel',[]);
            grid on;
            view(3);

            % celBodyData = mainUserData{1,1};
            contents = cellstr(get(handles.orbitingAboutCombo,'String'));
            bodyName = contents{get(handles.orbitingAboutCombo,'Value')};
            bodyInfo = app.celBodyData.(strtrim(lower(bodyName)));
%             dRad = bodyInfo.radius;
%             [X,Y,Z] = sphere(30);
%             surf(dRad*X,dRad*Y,dRad*Z);
%             colormap(bodyInfo.bodycolor);

            ma_initOrbPlot(app.manExeAssistGUI, app.orbitDispAxes, bodyInfo);

            axis equal;
            hold off;
        end

        % Value changed function: burnTruText
        function burnTruText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to burnTruText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of burnTruText as text
            %        str2double(get(hObject,'String')) returns contents of burnTruText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Button pushed function: computeBurnTimingInfoButton
        function computeBurnTimingInfoButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to computeBurnTimingInfoButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % guiUserData = get(handles.manExeAssistGUI,'UserData');
            % userData = get(guiUserData{1,1},'UserData');
            % celBodyData = userData{1,1};

            contents = cellstr(get(handles.orbitingAboutCombo,'String'));
            bodyName = contents{get(handles.orbitingAboutCombo,'Value')};
            bodyInfo = app.celBodyData.(strtrim(lower(bodyName)));
            bodyGmu = bodyInfo.gm;

            errMsg = {};

            eSMA = str2double(get(handles.eSMAText, 'String'));
            enteredStr = get(handles.eSMAText,'String');
            numberName = 'Elliptical orbit semi-major axis';
            lb = 1;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(eSMA, numberName, lb, ub, isInt, errMsg, enteredStr);

            eEcc = str2double(get(handles.eEccText, 'String'));
            enteredStr = get(handles.eEccText,'String');
            numberName = 'Elliptical orbit eccentricity';
            lb = 0;
            ub = 0.99999;
            isInt = false;
            errMsg = validateNumber(eEcc, numberName, lb, ub, isInt, errMsg, enteredStr);

            eInc = str2double(get(handles.eIncText, 'String'));
            enteredStr = get(handles.eIncText,'String');
            numberName = 'Elliptical orbit inclination';
            lb = 0;
            ub = 180;
            isInt = false;
            errMsg = validateNumber(eInc, numberName, lb, ub, isInt, errMsg, enteredStr);
            eInc=eInc*pi/180;

            eRAAN = str2double(get(handles.eRAANText, 'String'));
            enteredStr = get(handles.eRAANText,'String');
            numberName = 'Elliptical orbit RAAN';
            lb = 0;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(eRAAN, numberName, lb, ub, isInt, errMsg, enteredStr);
            eRAAN=eRAAN*pi/180;

            eArg = str2double(get(handles.eArgText, 'String'));
            enteredStr = get(handles.eArgText,'String');
            numberName = 'Elliptical orbit argument of periapse';
            lb = 0;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(eArg, numberName, lb, ub, isInt, errMsg, enteredStr);
            eArg=eArg*pi/180;

            progradeDV = str2double(get(handles.progradeDVText,'String'));
            enteredStr = get(handles.progradeDVText,'String');
            numberName = 'Prograde delta-v';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(progradeDV, numberName, lb, ub, isInt, errMsg, enteredStr);

            radialDV = str2double(get(handles.radialDVText,'String'));
            enteredStr = get(handles.radialDVText,'String');
            numberName = 'Radial delta-v';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(radialDV, numberName, lb, ub, isInt, errMsg, enteredStr);

            normalDV = str2double(get(handles.normalDVText,'String'));
            enteredStr = get(handles.normalDVText,'String');
            numberName = 'Normal delta-v';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(normalDV, numberName, lb, ub, isInt, errMsg, enteredStr);

            iniMass = str2double(get(handles.iniMassText,'String'));
            enteredStr = get(handles.iniMassText,'String');
            numberName = 'Initial mass';
            lb = 0.001;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(iniMass, numberName, lb, ub, isInt, errMsg, enteredStr);
            iniMass = iniMass * 1000;

            isp = str2double(get(handles.ispText,'String'));
            enteredStr = get(handles.ispText,'String');
            numberName = 'Specific impulse';
            lb = 1;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(isp, numberName, lb, ub, isInt, errMsg, enteredStr);

            thrust = str2double(get(handles.thrustText,'String'));
            enteredStr = get(handles.thrustText,'String');
            numberName = 'Thrust';
            lb = 0.001;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(thrust, numberName, lb, ub, isInt, errMsg, enteredStr);
            thrust = thrust * 1000;

            burnTru = str2double(get(handles.burnTruText,'String'));
            enteredStr = get(handles.burnTruText,'String');
            numberName = 'Burn true anomaly';
            lb = 0;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(burnTru, numberName, lb, ub, isInt, errMsg, enteredStr);
            burnTru = burnTru * pi/180;

            if(isempty(errMsg))
                targetDV = norm([progradeDV, radialDV, normalDV]);
                if(targetDV <= 0)
                    msgbox('Total magnitude of delta-v must be greater than 0.0 m/s.');
                else
                    try
                        g0 = 9.80665; %m/s^2;

                        mdot = thrust/(isp*g0);

                        halfDV = targetDV/2;

                        dtAll = (iniMass/mdot) * (1 - exp(-targetDV/(isp*g0)));
                        dtHalf = (iniMass/mdot) * (1 - exp(-halfDV/(isp*g0)));

                        ePeriod = computePeriod(eSMA, bodyGmu);
                        hRule = '---------------------------------------------';

                        paddLen = 29;
                        form = '%9.5f';
                        burnInfoText{1} = 'Delta-V Maneuver Execution Information';
                        burnInfoText{end+1} = hRule;
                        burnInfoText{end+1} = [paddStr('Total Delta-V = ',paddLen), num2str(targetDV/1000, form), ' km/s'];
                        burnInfoText{end+1} = [paddStr('Prograde Delta-V = ',paddLen), num2str(progradeDV, form), ' m/s'];
                        burnInfoText{end+1} = [paddStr('Radial Delta-V = ',paddLen), num2str(radialDV, form), ' m/s'];
                        burnInfoText{end+1} = [paddStr('Orbit Normal Delta-V = ',paddLen), num2str(normalDV, form), ' m/s'];
                        burnInfoText{end+1} = '---------------------';
                        burnInfoText{end+1} = [paddStr('Burn Start Time = ',paddLen), 'T-', num2str(dtHalf, form), ' sec'];
                        burnInfoText{end+1} = [paddStr('Burn End Time = ',paddLen), 'T+', num2str(-dtHalf+dtAll, form), ' sec'];
                        burnInfoText{end+1} = [paddStr('Burn Duration = ',paddLen), num2str(dtAll, form), ' sec'];
                        burnInfoText{end+1} = [paddStr('Orbit Period = ',paddLen), num2str(ePeriod, form), ' sec'];
                        set(handles.dvManInfoText, 'String', burnInfoText);

                        axes(handles.orbitDispAxes);
                        cla(handles.orbitDispAxes,'reset');
                        set(handles.orbitDispAxes,'XTickLabel',[]);
                        set(handles.orbitDispAxes,'YTickLabel',[]);
                        set(handles.orbitDispAxes,'ZTickLabel',[]);
                        hold on;
%                         dRad = bodyInfo.radius;
%                         [X,Y,Z] = sphere(30);
%                         surf(dRad*X,dRad*Y,dRad*Z);
%                         colormap(bodyInfo.bodycolor);

                        ma_initOrbPlot(app.manExeAssistGUI, app.orbitDispAxes, bodyInfo);

                        plotOrbit('k', eSMA, eEcc, eInc, eRAAN, eArg, 0, 2*pi, bodyGmu);

                        burnMean = computeMeanFromTrueAnom(burnTru, eEcc);
                        meanMotion = computeMeanMotion(eSMA, bodyGmu);
                        dtHalfMean = burnMean - meanMotion*dtHalf;
                        dtAllMean = dtHalfMean + meanMotion * dtAll;

                        dtHalfTru = AngleZero2Pi(computeTrueAnomFromMean(dtHalfMean, eEcc));
                        dtAllTru = AngleZero2Pi(computeTrueAnomFromMean(dtAllMean, eEcc));

                        [rVectManNode,~]=getStatefromKepler(eSMA, eEcc, eInc, eRAAN, eArg, burnTru, bodyGmu);
                        [rVectDtHalf,~]=getStatefromKepler(eSMA, eEcc, eInc, eRAAN, eArg, dtHalfTru, bodyGmu);
                        [rVectDtAll,~]=getStatefromKepler(eSMA, eEcc, eInc, eRAAN, eArg, dtAllTru, bodyGmu);

                        if(ePeriod < dtAll)
                            means = linspace(0, 2*pi, 100);
                        else
                            means = linspace(dtHalfMean, dtAllMean, 100);
                        end

                        x = 0;
                        y = 0;
                        z = 0;
                        for(i=1:length(means)) %#ok<*NO4LP> 
                            meanPoly = means(i);
                            truPoly = computeTrueAnomFromMean(meanPoly, eEcc);
                            %             disp(truPoly);
                            rVect = getStatefromKepler(eSMA, eEcc, eInc, eRAAN, eArg, truPoly, bodyGmu);
                            x(end+1) = rVect(1); %#ok<AGROW> 
                            y(end+1) = rVect(2); %#ok<AGROW> 
                            z(end+1) = rVect(3); %#ok<AGROW> 
                        end
                        hold on;
                        hPolyArc = fill3(x,y,z,'r');
                        alpha(hPolyArc,0.5);

                        hold on;
                        hNode = plot3(rVectManNode(1),rVectManNode(2),rVectManNode(3),...
                            '-ro',...
                            'LineWidth',1.0,...
                            'MarkerEdgeColor','k',...
                            'MarkerFaceColor','r',...
                            'MarkerSize',10);

                        hold on;
                        hStart = plot3(rVectDtHalf(1),rVectDtHalf(2),rVectDtHalf(3),...
                            '-go',...
                            'LineWidth',1.0,...
                            'MarkerEdgeColor','k',...
                            'MarkerFaceColor','g',...
                            'MarkerSize',10);

                        hold on;
                        hEnd = plot3(rVectDtAll(1),rVectDtAll(2),rVectDtAll(3),...
                            '-bo',...
                            'LineWidth',1.0,...
                            'MarkerEdgeColor','k',...
                            'MarkerFaceColor','b',...
                            'MarkerSize',10);
                        legendHandles = [hNode hStart hEnd hPolyArc];
                        legendStrs = {'Maneuver Node Location', 'Burn Start Location', 'Approx. Burn End Location', 'Approx. Burn Arc'};
                        legend(legendHandles, legendStrs, 'Location', 'SouthOutside', 'LineWidth', 1.5);


                        grid on;
                        axis equal;
                        view(3);

                        hold off;

                        applySelectedThemeToApp(app);
                    catch ME
                        msgbox(ME.message);
                    end
                end
            else
                msgbox(errMsg,'Errors were found in inputs','error');
            end
        end

        % Menu selected function: computeSysLvlProps
        function computeSysLvlProps_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to computeSysLvlProps (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            guiUserData = get(handles.manExeAssistGUI,'UserData');
            
            output = AppDesignerGUIOutput({false});
            computeSysLvlParams_App(guiUserData{2,1}, output);
            thrust = output.output{1};
            isp = output.output{2};
            enginesParams = output.output{3};
            
            if(~isempty(thrust) && ~isempty(isp))
                set(handles.thrustText, 'String', num2str(thrust));
                set(handles.ispText, 'String', num2str(isp));
                guiUserData{2,1} = enginesParams;
                set(handles.manExeAssistGUI,'UserData',guiUserData);
            end
        end

        % Menu selected function: copyOrbitToClipboardMenu
        function copyOrbitToClipboardMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to copyOrbitToClipboardMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % guiUserData = get(handles.manExeAssistGUI,'UserData');
            % userData = get(guiUserData{1,1},'UserData');
            % celBodyData = userData{1,1};

            contents = cellstr(get(handles.orbitingAboutCombo,'String'));
            cbName = contents{get(handles.orbitingAboutCombo,'Value')};
            cBodyInfo = app.celBodyData.(lower(strtrim(cbName)));

            copyOrbitToClipboardFromText([], handles.eSMAText, handles.eEccText, ...
                handles.eIncText, handles.eRAANText, handles.eArgText, ...
                [], true, cBodyInfo.id);
        end

        % Value changed function: eArgText
        function eArgText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to eArgText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of eArgText as text
            %        str2double(get(hObject,'String')) returns contents of eArgText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: eEccText
        function eEccText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to eEccText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of eEccText as text
            %        str2double(get(hObject,'String')) returns contents of eEccText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: eIncText
        function eIncText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to eIncText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of eIncText as text
            %        str2double(get(hObject,'String')) returns contents of eIncText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: eRAANText
        function eRAANText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to eRAANText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of eRAANText as text
            %        str2double(get(hObject,'String')) returns contents of eRAANText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: eSMAText
        function eSMAText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to eSMAText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of eSMAText as text
            %        str2double(get(hObject,'String')) returns contents of eSMAText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Menu selected function: getBurnParamsFromKspActiveVesselMenu
        function getBurnParamsFromKspActiveVesselMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getBurnParamsFromKspActiveVesselMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            nodes = getManeuverNodesFromKSPActiveVessel();
            if(isempty(nodes))
                warndlg('Could not find any maneuver nodes on active vessel in KSP.  Are you running KSP in the flight scene with KSPTOTConnect loaded?','No Manuever Nodes Found');
                return;
            end

            node = 1;

            orbit = getSingularOrbitFromKSPTOTConnect([]);
            orbitBodyInfo = getBodyInfoStructFromOrbit([orbit{3:9}]);

            % guiUserData = get(handles.manExeAssistGUI,'UserData');
            % userData = get(guiUserData{1,1},'UserData');
            % celBodyData = userData{1,1};
            bodyInfo = getBodyInfoByNumber(orbit{10}, app.celBodyData);

            [rVect, vVect] = getStateAtTime(orbitBodyInfo, nodes(node,1), bodyInfo.gm);
            [~, ~, ~, ~, ~, tru] = getKeplerFromState(rVect,vVect,bodyInfo.gm);

            set(handles.progradeDVText,'String', fullAccNum2Str(nodes(node,4)));
            set(handles.radialDVText,'String', fullAccNum2Str(nodes(node,2)));
            set(handles.normalDVText,'String', fullAccNum2Str(nodes(node,3)));
            set(handles.burnTruText,'String', fullAccNum2Str(rad2deg(tru)));
        end

        % Menu selected function: getOrbitFromKSPActiveVesselMenu
        function getOrbitFromKSPActiveVesselMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getOrbitFromKSPActiveVesselMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            orbitPanelGetOrbitFromKSPTOTConnectActiveVesselCallBack(handles.eSMAText, handles.eEccText, handles.eIncText, ...
                handles.eRAANText, handles.eArgText);
        end

        % Menu selected function: getOrbitFromKSPMenu
        function getOrbitFromKSPMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getOrbitFromKSPMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            orbitPanelGetOrbitFromKSPTOTConnectCallBack(handles.eSMAText, handles.eEccText, handles.eIncText, ...
                handles.eRAANText, handles.eArgText);
        end

        % Menu selected function: getOrbitFromSFSFile
        function getOrbitFromSFSFile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getOrbitFromSFSFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % guiUserData = get(handles.manExeAssistGUI,'UserData');
            % mainGUIHandle = guiUserData{1,1};
            orbitPanelGetOrbitFromSFSContextCallBack(handles.eSMAText, handles.eEccText, handles.eIncText, handles.eRAANText, handles.eArgText);
        end

        % Value changed function: iniMassText
        function iniMassText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to iniMassText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of iniMassText as text
            %        str2double(get(hObject,'String')) returns contents of iniMassText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: ispText
        function ispText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to ispText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of ispText as text
            %        str2double(get(hObject,'String')) returns contents of ispText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: normalDVText
        function normalDVText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to normalDVText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of normalDVText as text
            %        str2double(get(hObject,'String')) returns contents of normalDVText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Menu selected function: pasteOrbitFromClipboardMenu
        function pasteOrbitFromClipboardMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pasteOrbitFromClipboardMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % guiUserData = get(handles.manExeAssistGUI,'UserData');
            % userData = get(guiUserData{1,1},'UserData');
            % celBodyData = userData{1,1};

            pasteOrbitFromClipboard([], handles.eSMAText, handles.eEccText, ...
                handles.eIncText, handles.eRAANText, handles.eArgText, ...
                [], true, handles.orbitingAboutCombo, app.celBodyData);
        end

        % Value changed function: progradeDVText
        function progradeDVText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to progradeDVText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of progradeDVText as text
            %        str2double(get(hObject,'String')) returns contents of progradeDVText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: radialDVText
        function radialDVText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to radialDVText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of radialDVText as text
            %        str2double(get(hObject,'String')) returns contents of radialDVText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: thrustText
        function thrustText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to thrustText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of thrustText as text
            %        str2double(get(hObject,'String')) returns contents of thrustText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Callback function: uitoggletool2
        function uitoggletool2_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable rotation based on the
            % tool's current state.
            state = app.uitoggletool2.State;
            rotate3d(app.manExeAssistGUI, char(state));
        end

        % Callback function: uitoggletool1
        function uitoggletool1_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool1.State;
            pan(app.manExeAssistGUI, char(state));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.manExeAssistGUI = uifigure('Visible', 'off');
            colormap(app.manExeAssistGUI, 'parula');
            app.manExeAssistGUI.Name = 'Maneuver Execution Assistant';
            app.manExeAssistGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.manExeAssistGUI.Tag = 'manExeAssistGUI';
            app.uitoolbar1 = uitoolbar(app.manExeAssistGUI);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uitoggletool1 = uitoggletool(app.uitoolbar1);
            app.uitoggletool1.Tag = 'uitoggletool1';
            app.uitoggletool1.Tooltip = 'Pan';
            app.uitoggletool1.ClickedCallback = createCallbackFcn(app, @uitoggletool1_ClickedCallback, true);
            app.uitoggletool1.Icon = 'pan_image.png';
            app.uitoggletool2 = uitoggletool(app.uitoolbar1);
            app.uitoggletool2.Tag = 'uitoggletool2';
            app.uitoggletool2.Tooltip = 'Rotate 3D';
            app.uitoggletool2.ClickedCallback = createCallbackFcn(app, @uitoggletool2_ClickedCallback, true);
            app.uitoggletool2.Icon = '3d_rotate.png';
            app.GridLayout = uigridlayout(app.manExeAssistGUI);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.titleText = uilabel(app.GridLayout2);
            app.titleText.Tag = 'titleText';
            app.titleText.Text = {'KSP Trajectory Optimization Tool'; 'Maneuver Execution Assistant'};
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = 'mea_20230327.png';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.uipanel2 = uipanel(app.GridLayout4);
            app.uipanel2.Title = 'General Parameters';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout7 = uigridlayout(app.uipanel2);
            app.text10 = uilabel(app.GridLayout7);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Orbiting About';
            app.orbitingAboutCombo = uidropdown(app.GridLayout7);
            app.orbitingAboutCombo.Items = {'Pop-up Menu'};
            app.orbitingAboutCombo.Tag = 'orbitingAboutCombo';
            app.orbitingAboutCombo.Tooltip = 'This is the body about which the Initial Orbit is about.  Typically where you are before a burn is executed.';
            app.orbitingAboutCombo.Value = 'Pop-up Menu';
            app.uipanel1 = uipanel(app.GridLayout4);
            app.uipanel1.Title = 'Initial Orbit Info';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout8 = uigridlayout(app.uipanel1);
            app.text5 = uilabel(app.GridLayout8);
            app.text5.Tag = 'text5';
            app.text5.Text = 'SMA';
            app.text6 = uilabel(app.GridLayout8);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Ecc.';
            app.text7 = uilabel(app.GridLayout8);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Inc.';
            app.text8 = uilabel(app.GridLayout8);
            app.text8.Tag = 'text8';
            app.text8.Text = 'RAAN';
            app.text9 = uilabel(app.GridLayout8);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Arg. Peri.';
            app.eSMAText = uieditfield(app.GridLayout8, 'text');
            app.eSMAText.ValueChangedFcn = createCallbackFcn(app, @eSMAText_Callback, true);
            app.eSMAText.Tag = 'eSMAText';
            app.eSMAText.Tooltip = 'Semi-major axis of initial orbit.';
            app.eSMAText.Value = '700';
            app.eEccText = uieditfield(app.GridLayout8, 'text');
            app.eEccText.ValueChangedFcn = createCallbackFcn(app, @eEccText_Callback, true);
            app.eEccText.Tag = 'eEccText';
            app.eEccText.Tooltip = 'Eccentricity of initial orbit.';
            app.eEccText.Value = '0.0';
            app.eIncText = uieditfield(app.GridLayout8, 'text');
            app.eIncText.ValueChangedFcn = createCallbackFcn(app, @eIncText_Callback, true);
            app.eIncText.Tag = 'eIncText';
            app.eIncText.Tooltip = 'Inclination of initial orbit.';
            app.eIncText.Value = '0.0';
            app.eRAANText = uieditfield(app.GridLayout8, 'text');
            app.eRAANText.ValueChangedFcn = createCallbackFcn(app, @eRAANText_Callback, true);
            app.eRAANText.Tag = 'eRAANText';
            app.eRAANText.Tooltip = 'Right ascension of the ascending node of initial orbit.';
            app.eRAANText.Value = '0.0';
            app.eArgText = uieditfield(app.GridLayout8, 'text');
            app.eArgText.ValueChangedFcn = createCallbackFcn(app, @eArgText_Callback, true);
            app.eArgText.Tag = 'eArgText';
            app.eArgText.Tooltip = 'Argument of periapse of initial orbit.';
            app.eArgText.Value = '0.0';
            app.text1 = uilabel(app.GridLayout8);
            app.text1.Tag = 'text1';
            app.text1.Text = 'km';
            app.text2 = uilabel(app.GridLayout8);
            app.text2.Tag = 'text2';
            app.text2.Text = 'deg';
            app.text3 = uilabel(app.GridLayout8);
            app.text3.Tag = 'text3';
            app.text3.Text = 'deg';
            app.text4 = uilabel(app.GridLayout8);
            app.text4.Tag = 'text4';
            app.text4.Text = 'deg';
            app.uipanel3 = uipanel(app.GridLayout4);
            app.uipanel3.Title = 'Burn Parameters';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout9 = uigridlayout(app.uipanel3);
            app.text16 = uilabel(app.GridLayout9);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Prograde DV';
            app.text18 = uilabel(app.GridLayout9);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Radial DV';
            app.text20 = uilabel(app.GridLayout9);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Normal DV';
            app.text22 = uilabel(app.GridLayout9);
            app.text22.Tag = 'text22';
            app.text22.Text = 'True Anomaly';
            app.progradeDVText = uieditfield(app.GridLayout9, 'text');
            app.progradeDVText.ValueChangedFcn = createCallbackFcn(app, @progradeDVText_Callback, true);
            app.progradeDVText.Tag = 'progradeDVText';
            app.progradeDVText.Tooltip = 'The magnitude of the burn in the prograde direction';
            app.progradeDVText.Value = '0.0';
            app.radialDVText = uieditfield(app.GridLayout9, 'text');
            app.radialDVText.ValueChangedFcn = createCallbackFcn(app, @radialDVText_Callback, true);
            app.radialDVText.Tag = 'radialDVText';
            app.radialDVText.Tooltip = 'The magnitude of the burn in the radial direction';
            app.radialDVText.Value = '0.0';
            app.normalDVText = uieditfield(app.GridLayout9, 'text');
            app.normalDVText.ValueChangedFcn = createCallbackFcn(app, @normalDVText_Callback, true);
            app.normalDVText.Tag = 'normalDVText';
            app.normalDVText.Tooltip = 'The magnitude of the burn in the orbit normal direction';
            app.normalDVText.Value = '0.0';
            app.burnTruText = uieditfield(app.GridLayout9, 'text');
            app.burnTruText.ValueChangedFcn = createCallbackFcn(app, @burnTruText_Callback, true);
            app.burnTruText.Tag = 'burnTruText';
            app.burnTruText.Tooltip = 'The true anomaly at which the impulsive burn is scheduled.  Typically the location of the maneuver node.';
            app.burnTruText.Value = '0.0';
            app.text15 = uilabel(app.GridLayout9);
            app.text15.Tag = 'text15';
            app.text15.Text = 'm/s';
            app.text17 = uilabel(app.GridLayout9);
            app.text17.Tag = 'text17';
            app.text17.Text = 'm/s';
            app.text19 = uilabel(app.GridLayout9);
            app.text19.Tag = 'text19';
            app.text19.Text = 'm/s';
            app.text21 = uilabel(app.GridLayout9);
            app.text21.Tag = 'text21';
            app.text21.Text = 'deg';
            app.uipanel4 = uipanel(app.GridLayout4);
            app.uipanel4.Title = 'System-Level Parameters';
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout10 = uigridlayout(app.uipanel4);
            app.text24 = uilabel(app.GridLayout10);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Thrust';
            app.text26 = uilabel(app.GridLayout10);
            app.text26.Tag = 'text26';
            app.text26.Text = 'Spec. Imp.';
            app.text28 = uilabel(app.GridLayout10);
            app.text28.Tag = 'text28';
            app.text28.Text = 'Ini. Mass';
            app.thrustText = uieditfield(app.GridLayout10, 'text');
            app.thrustText.ValueChangedFcn = createCallbackFcn(app, @thrustText_Callback, true);
            app.thrustText.Tag = 'thrustText';
            app.thrustText.Tooltip = 'Total thrust of the spacecraft executing the burn.  Right click for calculator.';
            app.thrustText.Value = '0.0';
            app.ispText = uieditfield(app.GridLayout10, 'text');
            app.ispText.ValueChangedFcn = createCallbackFcn(app, @ispText_Callback, true);
            app.ispText.Tag = 'ispText';
            app.ispText.Tooltip = 'Average specific impulse of the spacecraft executing the burn.  Right click for calculator.';
            app.ispText.Value = '0.0';
            app.iniMassText = uieditfield(app.GridLayout10, 'text');
            app.iniMassText.ValueChangedFcn = createCallbackFcn(app, @iniMassText_Callback, true);
            app.iniMassText.Tag = 'iniMassText';
            app.iniMassText.Tooltip = 'Initial mass of the spacecraft executing the burn.';
            app.iniMassText.Value = '0.0';
            app.text23 = uilabel(app.GridLayout10);
            app.text23.Tag = 'text23';
            app.text23.Text = 'kN';
            app.text25 = uilabel(app.GridLayout10);
            app.text25.Tag = 'text25';
            app.text25.Text = 'sec';
            app.text27 = uilabel(app.GridLayout10);
            app.text27.Tag = 'text27';
            app.text27.Text = 'tons';
            app.Panel = uipanel(app.GridLayout3);
            app.GridLayout5 = uigridlayout(app.Panel);
            app.orbitDispAxes = uiaxes(app.GridLayout5);
            app.orbitDispAxes.Tag = 'orbitDispAxes';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.text29 = uilabel(app.GridLayout6);
            app.text29.Tag = 'text29';
            app.text29.Text = 'DV Maneuver Information';
            app.dvManInfoText = uitextarea(app.GridLayout6);
            app.dvManInfoText.Tag = 'dvManInfoText';
            app.dvManInfoText.Value = {'No Maneuver Computed Yet'};
            app.computeBurnTimingInfoButton = uibutton(app.GridLayout6, 'push');
            app.computeBurnTimingInfoButton.ButtonPushedFcn = createCallbackFcn(app, @computeBurnTimingInfoButton_Callback, true);
            app.computeBurnTimingInfoButton.Tag = 'computeBurnTimingInfoButton';
            app.computeBurnTimingInfoButton.Tooltip = 'Computes the burn timing information.';
            app.computeBurnTimingInfoButton.Text = 'Compute Burn Timing Information';
            app.orbitContextMenu = uicontextmenu(app.manExeAssistGUI);
            app.orbitContextMenu.Tag = 'orbitContextMenu';
            app.getOrbitFromSFSFile = uimenu(app.orbitContextMenu);
            app.getOrbitFromSFSFile.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromSFSFile_Callback, true);
            app.getOrbitFromSFSFile.Text = 'Get Orbit From SFS File';
            app.getOrbitFromSFSFile.Tag = 'getOrbitFromSFSFile';
            app.getOrbitFromKSPMenu = uimenu(app.orbitContextMenu);
            app.getOrbitFromKSPMenu.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromKSPMenu_Callback, true);
            app.getOrbitFromKSPMenu.Text = 'Get Orbit From KSP';
            app.getOrbitFromKSPMenu.Tag = 'getOrbitFromKSPMenu';
            app.getOrbitFromKSPActiveVesselMenu = uimenu(app.orbitContextMenu);
            app.getOrbitFromKSPActiveVesselMenu.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromKSPActiveVesselMenu_Callback, true);
            app.getOrbitFromKSPActiveVesselMenu.Text = 'Get Orbit From KSP (Active Vessel)';
            app.getOrbitFromKSPActiveVesselMenu.Tag = 'getOrbitFromKSPActiveVesselMenu';
            app.copyOrbitToClipboardMenu = uimenu(app.orbitContextMenu);
            app.copyOrbitToClipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @copyOrbitToClipboardMenu_Callback, true);
            app.copyOrbitToClipboardMenu.Text = 'Copy Orbit to Clipboard';
            app.copyOrbitToClipboardMenu.Tag = 'copyOrbitToClipboardMenu';
            app.pasteOrbitFromClipboardMenu = uimenu(app.orbitContextMenu);
            app.pasteOrbitFromClipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @pasteOrbitFromClipboardMenu_Callback, true);
            app.pasteOrbitFromClipboardMenu.Text = 'Paste Orbit From Clipboard';
            app.pasteOrbitFromClipboardMenu.Tag = 'pasteOrbitFromClipboardMenu';
            % Assign app.orbitContextMenu
            app.uipanel1.ContextMenu = app.orbitContextMenu;
            app.text5.ContextMenu = app.orbitContextMenu;
            app.text6.ContextMenu = app.orbitContextMenu;
            app.text7.ContextMenu = app.orbitContextMenu;
            app.text8.ContextMenu = app.orbitContextMenu;
            app.text9.ContextMenu = app.orbitContextMenu;
            app.eSMAText.ContextMenu = app.orbitContextMenu;
            app.eEccText.ContextMenu = app.orbitContextMenu;
            app.eIncText.ContextMenu = app.orbitContextMenu;
            app.eRAANText.ContextMenu = app.orbitContextMenu;
            app.eArgText.ContextMenu = app.orbitContextMenu;
            app.text1.ContextMenu = app.orbitContextMenu;
            app.text2.ContextMenu = app.orbitContextMenu;
            app.text3.ContextMenu = app.orbitContextMenu;
            app.text4.ContextMenu = app.orbitContextMenu;
            app.sysLevelProps = uicontextmenu(app.manExeAssistGUI);
            app.sysLevelProps.Tag = 'sysLevelProps';
            app.computeSysLvlProps = uimenu(app.sysLevelProps);
            app.computeSysLvlProps.MenuSelectedFcn = createCallbackFcn(app, @computeSysLvlProps_Callback, true);
            app.computeSysLvlProps.Text = 'Compute System Level Properties';
            app.computeSysLvlProps.Tag = 'computeSysLvlProps';
            % Assign app.sysLevelProps
            app.uipanel4.ContextMenu = app.sysLevelProps;
            app.text24.ContextMenu = app.sysLevelProps;
            app.text26.ContextMenu = app.sysLevelProps;
            app.text28.ContextMenu = app.sysLevelProps;
            app.thrustText.ContextMenu = app.sysLevelProps;
            app.ispText.ContextMenu = app.sysLevelProps;
            app.iniMassText.ContextMenu = app.sysLevelProps;
            app.text23.ContextMenu = app.sysLevelProps;
            app.text25.ContextMenu = app.sysLevelProps;
            app.text27.ContextMenu = app.sysLevelProps;
            app.burnParamsContextMenu = uicontextmenu(app.manExeAssistGUI);
            app.burnParamsContextMenu.Tag = 'burnParamsContextMenu';
            app.getBurnParamsFromKspActiveVesselMenu = uimenu(app.burnParamsContextMenu);
            app.getBurnParamsFromKspActiveVesselMenu.MenuSelectedFcn = createCallbackFcn(app, @getBurnParamsFromKspActiveVesselMenu_Callback, true);
            app.getBurnParamsFromKspActiveVesselMenu.Text = 'Read Next Maneuver Node from KSP Active Vessel';
            app.getBurnParamsFromKspActiveVesselMenu.Tag = 'getBurnParamsFromKspActiveVesselMenu';
            % Assign app.burnParamsContextMenu
            app.uipanel3.ContextMenu = app.burnParamsContextMenu;
            app.text16.ContextMenu = app.burnParamsContextMenu;
            app.text18.ContextMenu = app.burnParamsContextMenu;
            app.text20.ContextMenu = app.burnParamsContextMenu;
            app.text22.ContextMenu = app.burnParamsContextMenu;
            app.progradeDVText.ContextMenu = app.burnParamsContextMenu;
            app.radialDVText.ContextMenu = app.burnParamsContextMenu;
            app.normalDVText.ContextMenu = app.burnParamsContextMenu;
            app.burnTruText.ContextMenu = app.burnParamsContextMenu;
            app.text15.ContextMenu = app.burnParamsContextMenu;
            app.text17.ContextMenu = app.burnParamsContextMenu;
            app.text19.ContextMenu = app.burnParamsContextMenu;
            app.text21.ContextMenu = app.burnParamsContextMenu;
            % Show the figure after all components are created
            app.manExeAssistGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manExeAssistGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.manExeAssistGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)manExeAssistGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.manExeAssistGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.manExeAssistGUI)
        end
    end
end