classdef maxDeltaVInputGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        maxDeltaVInputGUI  matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        GridLayout2        matlab.ui.container.GridLayout
        maxDVText          matlab.ui.control.EditField
        text3              matlab.ui.control.Label
        okayButton         matlab.ui.control.Button
        dvtypelabel        matlab.ui.control.Label
        text2              matlab.ui.control.Label
    end


    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)

        function saveAndClose(app, handles)
            try
                app.output.output{1} = str2double(get(handles.maxDVText,'String'));
                app.output.output{2} = handles.output;

                close(handles.maxDeltaVInputGUI);
            catch
                %                 varargout{1} = -1;
                %                 maxDV = 0.0;
                app.output.output{1} = 0;
                app.output.output{2} = -1;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function maxDeltaVInputGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to maxDeltaVInputGUI (see VARARGIN)

            % Choose default command line output for maxDeltaVInputGUI
            handles.output = hObject;

            % Update handles structure
            guidata(hObject, handles);
            
            centerUIFigure(app.maxDeltaVInputGUI);
            applySelectedThemeToApp(app);

            %Possible fix for people with display issues.
%             checkForCharUnitsInGUI(hObject);

            options = varargin{1};
            deltaVStrName = deltaVTypeEnum(options.quant2opt);
            dvTypeStr =  {'Delta-V To Optimize:', deltaVStrName};
            set(handles.dvtypelabel, 'String', dvTypeStr);
            
            app.output = varargin{2};

            % UIWAIT makes maxDeltaVInputGUI wait for user response (see UIRESUME)
            uiwait(handles.maxDeltaVInputGUI);
        end

        % Value changed function: maxDVText
        function maxDVText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to maxDVText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of maxDVText as text
            %        str2double(get(hObject,'String')) returns contents of maxDVText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Button pushed function: okayButton
        function okayButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to okayButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = {};

            maxDV = str2double(get(handles.maxDVText,'String'));
            enteredStr = get(handles.maxDVText,'String');
            numberName = 'Maximum delta-v';
            lb = 0.01;
            ub = 100;
            isInt = false;
            errMsg = validateNumber(maxDV, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
%                 uiresume(handles.maxDeltaVInputGUI);
                app.saveAndClose(handles);
            else
%                 msgbox(errMsg,'Errors were found in maximum delta-v inputs','error');
                uialert(app.maxDeltaVInputGUI, errMsg, 'Input Errors', "Icon","error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.maxDeltaVInputGUI = uifigure('Visible', 'off');
            colormap(app.maxDeltaVInputGUI, 'parula');
            app.maxDeltaVInputGUI.Name = 'Enter Max. DV';
            app.maxDeltaVInputGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.maxDeltaVInputGUI.Tag = 'maxDeltaVInputGUI';
            app.GridLayout = uigridlayout(app.maxDeltaVInputGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Enter Maximum Delta-V: ';
            app.dvtypelabel = uilabel(app.GridLayout);
            app.dvtypelabel.Tag = 'dvtypelabel';
            app.dvtypelabel.Text = 'Delta-V Type:';
            app.okayButton = uibutton(app.GridLayout, 'push');
            app.okayButton.ButtonPushedFcn = createCallbackFcn(app, @okayButton_Callback, true);
            app.okayButton.Tag = 'okayButton';
            app.okayButton.Tooltip = 'Select departure/arrival data on porkchop (click on porkchop plot)';
            app.okayButton.Text = 'OK';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.text3 = uilabel(app.GridLayout2);
            app.text3.Tag = 'text3';
            app.text3.Text = 'km/s';
            app.maxDVText = uieditfield(app.GridLayout2, 'text');
            app.maxDVText.ValueChangedFcn = createCallbackFcn(app, @maxDVText_Callback, true);
            app.maxDVText.Tag = 'maxDVText';
            app.maxDVText.Tooltip = 'Seconds from game start (see your persistence.sfs file)';
            app.maxDVText.Value = '1.0';
            % Show the figure after all components are created
            app.maxDeltaVInputGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = maxDeltaVInputGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.maxDeltaVInputGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)maxDeltaVInputGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.maxDeltaVInputGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.maxDeltaVInputGUI)
        end
    end
end