classdef multiFlyByManeuverSequencer_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        multiFlybyManSeqGUI            matlab.ui.Figure
        uitoolbar1                     matlab.ui.container.Toolbar
        uitoggletool1                  matlab.ui.container.toolbar.ToggleTool
        uitoggletool2                  matlab.ui.container.toolbar.ToggleTool
        uitoggletool3                  matlab.ui.container.toolbar.ToggleTool
        uitoggletool4                  matlab.ui.container.toolbar.ToggleTool
        GridLayout26                   matlab.ui.container.GridLayout
        GridLayout27                   matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
        text2_2                        matlab.ui.control.Label
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout16                   matlab.ui.container.GridLayout
        GridLayout22                   matlab.ui.container.GridLayout
        SaveResultstoFileForLVDButton  matlab.ui.control.Button
        saveTextResultsToFileButton    matlab.ui.control.Button
        uipanel13                      matlab.ui.container.Panel
        GridLayout17                   matlab.ui.container.GridLayout
        GridLayout21                   matlab.ui.container.GridLayout
        text62                         matlab.ui.control.Label
        maxIterText                    matlab.ui.control.EditField
        text63                         matlab.ui.control.Label
        GridLayout20                   matlab.ui.container.GridLayout
        text60                         matlab.ui.control.Label
        popSizeText                    matlab.ui.control.EditField
        text61                         matlab.ui.control.Label
        GridLayout19                   matlab.ui.container.GridLayout
        text58                         matlab.ui.control.Label
        maxArriveVInfText              matlab.ui.control.EditField
        text59                         matlab.ui.control.Label
        GridLayout18                   matlab.ui.container.GridLayout
        text56                         matlab.ui.control.Label
        maxDepartVInfText              matlab.ui.control.EditField
        text57                         matlab.ui.control.Label
        includeArrivalVInfCheckbox     matlab.ui.control.CheckBox
        includeDepartVInfCheckbox      matlab.ui.control.CheckBox
        computeFlybyManSeqButton       matlab.ui.control.Button
        dvManInfoText                  matlab.ui.control.TextArea
        dvManInfoLabel                 matlab.ui.control.Label
        transferOrbitsText             matlab.ui.control.TextArea
        xferOrbitTxtBoxLabel           matlab.ui.control.Label
        hyperbolicOrbitsText           matlab.ui.control.TextArea
        hypDepAndFlybyOrbitsLabel      matlab.ui.control.Label
        GridLayout15                   matlab.ui.container.GridLayout
        DisplayTabGroup                matlab.ui.container.TabGroup
        TransferOrbitsTab              matlab.ui.container.Tab
        GridLayout23                   matlab.ui.container.GridLayout
        dispAxesXfer                   matlab.ui.control.UIAxes
        DepartureOrbitTab              matlab.ui.container.Tab
        GridLayout24                   matlab.ui.container.GridLayout
        dispAxesDepart                 matlab.ui.control.UIAxes
        noFeasibleSolLabel             matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        uipanel8                       matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        waypointTable                  matlab.ui.control.Table
        GridLayout9                    matlab.ui.container.GridLayout
        deleteWayPointRowButton        matlab.ui.control.Button
        addWayPointRowButton           matlab.ui.control.Button
        iniEllipOrbitPanel             matlab.ui.container.Panel
        GridLayout14                   matlab.ui.container.GridLayout
        text45_2                       matlab.ui.control.Label
        text45                         matlab.ui.control.Label
        text41                         matlab.ui.control.Label
        text13                         matlab.ui.control.Label
        text12                         matlab.ui.control.Label
        text11                         matlab.ui.control.Label
        eEpochText                     matlab.ui.control.EditField
        eMAText                        matlab.ui.control.EditField
        eArgText                       matlab.ui.control.EditField
        eRAANText                      matlab.ui.control.EditField
        eIncText                       matlab.ui.control.EditField
        eEccText                       matlab.ui.control.EditField
        text46                         matlab.ui.control.Label
        text42                         matlab.ui.control.Label
        text18                         matlab.ui.control.Label
        text17                         matlab.ui.control.Label
        text16                         matlab.ui.control.Label
        text15                         matlab.ui.control.Label
        text14                         matlab.ui.control.Label
        eSMAText                       matlab.ui.control.EditField
        text10                         matlab.ui.control.Label
        flightTimeBndsPanel            matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        GridLayout25                   matlab.ui.container.GridLayout
        maxDeltaVText                  matlab.ui.control.EditField
        text49_2                       matlab.ui.control.Label
        text47_2                       matlab.ui.control.Label
        text47                         matlab.ui.control.Label
        maxNumRevsText                 matlab.ui.control.EditField
        GridLayout13                   matlab.ui.container.GridLayout
        text39                         matlab.ui.control.Label
        text38                         matlab.ui.control.Label
        GridLayout12                   matlab.ui.container.GridLayout
        maxFlightTimeText              matlab.ui.control.EditField
        minFlightTimeText              matlab.ui.control.EditField
        GridLayout11                   matlab.ui.container.GridLayout
        text37                         matlab.ui.control.Label
        text36                         matlab.ui.control.Label
        uipanel2                       matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        text1                          matlab.ui.control.Label
        GridLayout7                    matlab.ui.container.GridLayout
        maxMissionDurationText         matlab.ui.control.EditField
        text55                         matlab.ui.control.Label
        GridLayout6                    matlab.ui.container.GridLayout
        minCbPeriText                  matlab.ui.control.EditField
        text49                         matlab.ui.control.Label
        GridLayout5                    matlab.ui.container.GridLayout
        launchWindowCloseText          matlab.ui.control.EditField
        text6                          matlab.ui.control.Label
        GridLayout4                    matlab.ui.container.GridLayout
        launchWindowOpenText           matlab.ui.control.EditField
        text3                          matlab.ui.control.Label
        text54                         matlab.ui.control.Label
        text48                         matlab.ui.control.Label
        numOfRunsText                  matlab.ui.control.EditField
        text40                         matlab.ui.control.Label
        launchWinCloseLabel            matlab.ui.control.Label
        text5                          matlab.ui.control.Label
        launchWinOpenLabel             matlab.ui.control.Label
        text2                          matlab.ui.control.Label
        centralBodyCombo               matlab.ui.control.DropDown
        launchWinContxtMenu            matlab.ui.container.ContextMenu
        enterUTAsDateTime              matlab.ui.container.Menu
        getOrbitFromKSPFile            matlab.ui.container.ContextMenu
        getOrbitFromSFSFile            matlab.ui.container.Menu
        getOrbitFromKSP                matlab.ui.container.Menu
        getOrbitFromKSPActiveVesselMenu  matlab.ui.container.Menu
        copyOrbitToClipboardMenu       matlab.ui.container.Menu
        pasteOrbitFromClipboardMenu    matlab.ui.container.Menu
        enterDateTimeAndGetOrbit       matlab.ui.container.ContextMenu
        enterUTAsDateTime_1            matlab.ui.container.Menu
        getOrbitFromSFSFile_1          matlab.ui.container.Menu
        getOrbitFromKSP_1              matlab.ui.container.Menu
        getOrbitFromKSPActiveVesselMenu_1  matlab.ui.container.Menu
        copyOrbitToClipboardMenu_1     matlab.ui.container.Menu
        pasteOrbitFromClipboardMenu_1  matlab.ui.container.Menu
        uploadManeuver2KSP             matlab.ui.container.ContextMenu
        uploadManeuver                 matlab.ui.container.Menu
    end


    methods (Access = private)
        function cNotViolated = constraintCellFunc(~, rowC)
            cNotViolated = all(rowC <= 0);
        end

        function childrenNames = getValidWayPtsFromParent(~, celBodyData, hCentralBodyCombo)
            contents = cellstr(get(hCentralBodyCombo,'String'));
            cbName = contents{get(hCentralBodyCombo,'Value')};
            [~, childrenNames] = getChildrenOfParentInfo(celBodyData, cbName);
        end

        function initDispAxes(~, hdispAxesXfer)
            hAxis = hdispAxesXfer;
            axes(hAxis);
            cla(hAxis, 'reset');
            grid on;
            set(hAxis,'XTick',[], 'XTickMode', 'manual');
            set(hAxis,'YTick',[], 'YTickMode', 'manual');
            set(hAxis,'ZTick',[], 'ZTickMode', 'manual');
            view(3);
        end

        function initFlightTimeBounds(~, handles, celBodyData)
            wayPtBodies = {};
            waypts = get(handles.waypointTable,'Data');
            for(i=1:length(waypts))
                wayPtBodies{end+1} = celBodyData.(lower(waypts{i})); %#ok<AGROW>
            end

            [lb, ub] = getMultiFlybyLBUB(0, 1, wayPtBodies, celBodyData);
            numLegs = length(wayPtBodies)-1;

            lb = lb(2:1+numLegs);
            ub = ub(2:1+numLegs);
            maxRevs = zeros(1,numLegs);
            maxDv = 1E99*ones(1,numLegs);
            bnds = [lb',ub', maxRevs', maxDv'];
            set(handles.flightTimeBndsPanel,'UserData',bnds);
        end

        function initWayPtTable(app, celBodyData, hWayPtTable, hCentralBodyCombo)
            childrenNames = getValidWayPtsFromParent(app, celBodyData, hCentralBodyCombo);
            childrenNames = childrenNames';

            [mem,Inds] = ismember({'Kerbin','Eve','Duna'},childrenNames);
            if(all(mem))
                initData = childrenNames(Inds);
            else
                if(length(childrenNames)>=3)
                    initData = childrenNames(1:3);
                else
                    initData = childrenNames(1:end);
                end
            end

            set(hWayPtTable,'Data',initData);
            set(hWayPtTable,'ColumnFormat',{childrenNames'});
            set(hWayPtTable,'ColumnName',{'Waypoint'});
            set(hWayPtTable,'ColumnWidth',{135});
            set(hWayPtTable,'ColumnEditable',true);

            set(hWayPtTable,'UserData',size(initData));
        end

        function setCentralBodyCombo(~, celBodyData, hCentralBodyCombo)
            celBodyFields = fieldnames(celBodyData);
            validBodies = {};
            for(i=1:length(celBodyFields)) %#ok<*NO4LP>
                bodyInfo = celBodyData.(celBodyFields{i});
                [children, ~] = getChildrenOfParentInfo(celBodyData, bodyInfo.name);
                if(bodyInfo.canbecentral==1 && length(children)>=3)
                    validBodies{end+1} = bodyInfo.name; %#ok<AGROW>
                end
            end

            set(hCentralBodyCombo, 'String', char(validBodies));
        end

        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.

            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool1, app.uitoggletool2, app.uitoggletool3, app.uitoggletool4];
            interactiveTools(event.Source == interactiveTools) = [];

            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');

            % Set figure interactions to 'off'.
            datacursormode(app.multiFlybyManSeqGUI, 'off')
            rotate3d(app.multiFlybyManSeqGUI, 'off');
            pan(app.multiFlybyManSeqGUI, 'off');
            zoom(app.multiFlybyManSeqGUI,'off');
        end

        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)

            % Load data for component configuration
            componentData = load('multiFlyByManeuverSequencer_App.mat');

            % Set component properties that require runtime configuration
            app.waypointTable.BackgroundColor = [1 1 1;0.9608 0.9608 0.9608];
            app.waypointTable.ColumnFormat = {{'Moho' 'Kerbin' 'Eve' 'Duna'}};
            app.waypointTable.Data = componentData.waypointTable.Data;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function multiFlyByManeuverSequencer_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to multiFlyByManeuverSequencer (see VARARGIN)

            % Choose default command line output for multiFlyByManeuverSequencer
            handles.output = hObject;
            
            app.multiFlybyManSeqGUI.Visible = 'off'; drawnow;
            centerUIFigure(app.multiFlybyManSeqGUI);
            applySelectedThemeToApp(app);

            % Update handles structure
            guidata(hObject, handles);

            %Possible fix for people with display issues.
            % checkForCharUnitsInGUI(hObject);

            % mainGUIHandle = varargin{1};
            % mainGUIUserData = get(mainGUIHandle,'UserData');
            % setappdata(hObject,'MainGUIHandle',mainGUIHandle);
            % celBodyData = mainGUIUserData{1,1};
            celBodyData = varargin{1};
            setappdata(hObject,'celBodyData',celBodyData);
            setappdata(handles.multiFlybyManSeqGUI,'Output',[]);

            set(handles.dvManInfoText,'UIContextMenu',handles.uploadManeuver2KSP);

            setCentralBodyCombo(app, celBodyData, handles.centralBodyCombo);
            initWayPtTable(app, celBodyData, handles.waypointTable, handles.centralBodyCombo);
            initDispAxes(app, app.dispAxesXfer);
            initDispAxes(app, app.dispAxesDepart);
            initFlightTimeBounds(app, handles, celBodyData);

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);

            launchWindowOpenText_Callback(app, evt);
            launchWindowCloseText_Callback(app, evt);

            eventdataWpT.Indices = [1 1];
            eventdataWpT.Source = app.multiFlybyManSeqGUI;
            eventdataWpT.EventName = 'CellSelectionChanged';
            waypointTable_CellSelectionCallback(app, eventdataWpT);

            app.multiFlybyManSeqGUI.Visible = 'on';
        end

        % Button pushed function: addWayPointRowButton
        function addWayPointRowButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addWayPointRowButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            celBodyData = getappdata(handles.multiFlybyManSeqGUI,'celBodyData');

            wayPtData = get(handles.waypointTable,'Data');
            validChildren = get(handles.waypointTable,'ColumnFormat');
            wayPtData{end+1,1} = validChildren{1}{1};
            set(handles.waypointTable,'Data',wayPtData);

            wayPtBodies = {};
            for(i=1:length(wayPtData))
                wayPtBodies{end+1} = celBodyData.(lower(wayPtData{i})); %#ok<AGROW>
            end

            bnds = get(handles.flightTimeBndsPanel,'UserData');
            [lb, ub] = getMultiFlybyLBUB(0, 1, wayPtBodies, celBodyData);
            numLegs = length(wayPtBodies)-1;
            bnds(end+1,1) = lb(numLegs+1);
            bnds(end,2) = ub(numLegs+1);
            bnds(end,3) = 0;
            bnds(end,4) = 1E99;
            set(handles.flightTimeBndsPanel,'UserData',bnds);
        end

        % Value changed function: centralBodyCombo
        function centralBodyCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to centralBodyCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: contents = cellstr(get(hObject,'String')) returns centralBodyCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from centralBodyCombo
            celBodyData = getappdata(handles.multiFlybyManSeqGUI,'celBodyData');
            initWayPtTable(app, celBodyData, handles.waypointTable, handles.centralBodyCombo);

            eventdataWpT.Indices = [1 1];
            waypointTable_CellSelectionCallback(app, eventdataWpT);
        end

        % Button pushed function: computeFlybyManSeqButton
        function computeFlybyManSeqButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to computeFlybyManSeqButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = {};

            celBodyData = getappdata(handles.multiFlybyManSeqGUI,'celBodyData');

            lWindOpen = str2double(get(handles.launchWindowOpenText,'String'));
            enteredStr = get(handles.launchWindowOpenText,'String');
            numberName = 'Launch window open time';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(lWindOpen, numberName, lb, ub, isInt, errMsg, enteredStr);

            lWindClse = str2double(get(handles.launchWindowCloseText,'String'));
            enteredStr = get(handles.launchWindowCloseText,'String');
            numberName = 'Launch window close time';
            lb = lWindOpen+1;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(lWindClse, numberName, lb, ub, isInt, errMsg, enteredStr);

            wayPtBodies = {};
            waypts = get(handles.waypointTable,'Data');
            for(i=1:length(waypts))
                wayPtBodies{end+1} = celBodyData.(lower(waypts{i})); %#ok<AGROW>
            end

            if(length(wayPtBodies)<2)
                errMsg = {'At least two way points are required.'};
            end

            eSMA = str2double(get(handles.eSMAText, 'String'));
            enteredStr = get(handles.eSMAText,'String');
            numberName = 'Elliptical orbit semi-major axis';
            lb = 1;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(eSMA, numberName, lb, ub, isInt, errMsg, enteredStr);

            eEcc = str2double(get(handles.eEccText, 'String'));
            enteredStr = get(handles.eEccText,'String');
            numberName = 'Elliptical orbit eccentricity';
            lb = 0;
            ub = 0.99999;
            isInt = false;
            errMsg = validateNumber(eEcc, numberName, lb, ub, isInt, errMsg, enteredStr);

            eInc = str2double(get(handles.eIncText, 'String'));
            enteredStr = get(handles.eIncText,'String');
            numberName = 'Elliptical orbit inclination';
            lb = 0;
            ub = 180;
            isInt = false;
            errMsg = validateNumber(eInc, numberName, lb, ub, isInt, errMsg, enteredStr);
            eInc=eInc*pi/180;

            eRAAN = str2double(get(handles.eRAANText, 'String'));
            enteredStr = get(handles.eRAANText,'String');
            numberName = 'Elliptical orbit RAAN';
            lb = 0;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(eRAAN, numberName, lb, ub, isInt, errMsg, enteredStr);
            eRAAN=eRAAN*pi/180;

            eArg = str2double(get(handles.eArgText, 'String'));
            enteredStr = get(handles.eArgText,'String');
            numberName = 'Elliptical orbit argument of periapse';
            lb = 0;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(eArg, numberName, lb, ub, isInt, errMsg, enteredStr);
            eArg=eArg*pi/180;

            eMA = str2double(get(handles.eMAText,'String'));
            enteredStr = get(handles.eMAText,'String');
            numberName = 'Orbit Mean Anomaly';
            lb = 0;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(eMA, numberName, lb, ub, isInt, errMsg, enteredStr);
            eMA=eMA*pi/180;

            eEpoch = str2double(get(handles.eEpochText,'String'));
            enteredStr = get(handles.eEpochText,'String');
            numberName = 'Orbit Epoch';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(eEpoch, numberName, lb, ub, isInt, errMsg, enteredStr);

            numRuns = str2double(get(handles.numOfRunsText, 'String'));
            enteredStr = get(handles.numOfRunsText,'String');
            numberName = 'Number of Runs';
            lb = 1;
            ub = 1000;
            isInt = true;
            errMsg = validateNumber(numRuns, numberName, lb, ub, isInt, errMsg, enteredStr);

            Rps = NaN(1,length(wayPtBodies));
            for(i=1:length(wayPtBodies))
                bodyInfo = wayPtBodies{i};
                [~, rPe] = computeApogeePerigee(bodyInfo.sma, bodyInfo.ecc);
                Rps(i) = rPe;
            end
            maxMinXferRad = min(Rps);

            minCbPeriHgt = str2double(get(handles.minCbPeriText, 'String'));
            enteredStr = get(handles.minCbPeriText,'String');
            numberName = 'Min CB Periapsis Height';
            lb = 0;
            ub = maxMinXferRad;
            isInt = false;
            errMsg = validateNumber(minCbPeriHgt, numberName, lb, ub, isInt, errMsg, enteredStr);

            maxMsnDur = str2double(get(handles.maxMissionDurationText, 'String'));
            enteredStr = get(handles.maxMissionDurationText,'String');
            numberName = 'Maximum Mission Duration';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(maxMsnDur, numberName, lb, ub, isInt, errMsg, enteredStr);

            maxDepartVInf = str2double(get(handles.maxDepartVInfText, 'String'));
            enteredStr = get(handles.maxDepartVInfText,'String');
            numberName = 'Maximum Departure Hyp. Excess Velocity';
            lb = 0.001;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(maxDepartVInf, numberName, lb, ub, isInt, errMsg, enteredStr);

            maxArriveVInf = str2double(get(handles.maxArriveVInfText, 'String'));
            enteredStr = get(handles.maxArriveVInfText,'String');
            numberName = 'Maximum Arrival Hyp. Excess Velocity';
            lb = 0.001;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(maxArriveVInf, numberName, lb, ub, isInt, errMsg, enteredStr);

            popSize = str2double(get(handles.popSizeText, 'String'));
            enteredStr = get(handles.popSizeText,'String');
            numberName = 'Population Size';
            lb = 50;
            ub = realmax;
            isInt = true;
            errMsg = validateNumber(popSize, numberName, lb, ub, isInt, errMsg, enteredStr);

            numGen = str2double(get(handles.maxIterText, 'String'));
            enteredStr = get(handles.maxIterText,'String');
            numberName = 'Maximum Iterations';
            lb = 1;
            ub = realmax;
            isInt = true;
            errMsg = validateNumber(numGen, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                contents = cellstr(get(handles.centralBodyCombo,'String'));
                cbName = contents{get(handles.centralBodyCombo,'Value')};
                cBodyInfo = celBodyData.(lower(strtrim(cbName)));

                lWindDef = [lWindOpen, lWindClse-lWindOpen];

                %         popSize = 750;
                %         popSize = 10000;
                %         numGen = 1000;
                %         numGen = 100;

                bnds = get(handles.flightTimeBndsPanel,'UserData');

                if(numRuns > 1)
                    msg = sprintf('Executing Sequence of MFMS Runs... [%u/%u]', 1, numRuns);
%                     hWaitbar = waitbar(0,msg);
                    hWaitbar = uiprogressdlg(app.multiFlybyManSeqGUI, "Message",msg, 'Title','Multi-flyby Maneuver Sequencer', 'Icon','info', 'Value',0, 'Cancelable',true);
                else %numRuns == 1, normal case
                    hWaitbar = uiprogressdlg(app.multiFlybyManSeqGUI, "Message",'Optimizing flyby trajectories.  Please wait...', 'Title','Multi-flyby Maneuver Sequencer', 'Icon','info', 'Indeterminate',true);
                end

                includeDepartVInf = logical(handles.includeDepartVInfCheckbox.Value);
                includeArrivalVInf = logical(handles.includeArrivalVInfCheckbox.Value);

                runs = cell(numRuns,12);
                for(i=1:numRuns)
                    [x, dv, rp, orbitsIn, orbitsOut, xferOrbits, deltaVVect, vInfDNorm, c, vInfDepart, vInfArrive, numRev] = ...
                        multiFlybyExec(wayPtBodies,lWindDef,bnds,minCbPeriHgt,maxMsnDur,popSize,numGen,includeDepartVInf,includeArrivalVInf,maxDepartVInf,maxArriveVInf, eSMA, eEcc, eInc, eRAAN, eArg, celBodyData);

                    if(isempty(x))
                        return; %we hit an error condition in multiFlybyExec()
                    end

                    runs(i,:) = {x, dv, rp, orbitsIn, orbitsOut, xferOrbits, deltaVVect, vInfDNorm, c, vInfDepart, vInfArrive, numRev};

                    if(numRuns > 1)
                        validRuns = runs(cellfun(@app.constraintCellFunc,runs(1:i,9)),:);
                        if(~isempty(validRuns))
                            [minDv,~] = min(cell2mat(validRuns(:,2)));
                            I = find(ismember(cell2mat(runs(:,2)),minDv),1,'last');
                        else
                            [minDv,I] = min(cell2mat(runs(:,2)));
                        end

                        msg = sprintf('Executing Sequence of MFMS Runs... [%u/%u]\nBest current score: %f (run %u)', i+1, numRuns, minDv, I);
%                         waitbar(i/numRuns,hWaitbar,msg);
                        hWaitbar.Value = i/numRuns;
                        hWaitbar.Message = msg;

                        if(hWaitbar.CancelRequested)
                            break;
                        end
                    end
                end

                hGAPlot = findobj('Name','Genetic Algorithm');
                if(ishandle(hGAPlot))
                    close(hGAPlot);
                end
%                 if(numRuns > 1)
                close(hWaitbar);
%                 end

                validRuns = runs(cellfun(@app.constraintCellFunc,runs(:,9)),:);
                if(~isempty(validRuns))
                    [~,I] = min(cell2mat(validRuns(:,2)));
                else
                    [~,I] = min(cell2mat(runs(:,2)));
                end

                [x, dv, rp, orbitsIn, orbitsOut, xferOrbits, deltaVVect, vInfDNorm, c, vInfDepart, vInfArrive, numRev] = runs{I,:};

                %         MainGUIHandle = getappdata(handles.multiFlybyManSeqGUI,'MainGUIHandle');
                %         mainGUIUserData = get(MainGUIHandle,'UserData');
                %         mainUiOptions = mainGUIUserData{1,9};
                %         numComputeDepart = mainUiOptions.departplotnumoptiters;
                numComputeDepart = 25;

                hUiProgDlg = uiprogressdlg(app.multiFlybyManSeqGUI, 'Title','Multi-Flyby Maneuver Sequencer');
                
                [dVDepartVect, dVDepartVectNTW, eRDepartVect, hOrbit, eTA] = computeDepartureOrbit(eSMA, eEcc, eInc, eRAAN, eArg, eMA, eEpoch, wayPtBodies{1}.gm, vInfDepart, xferOrbits(1,8), xferOrbits(1,9), numComputeDepart, pi, 0, 2*pi, waypts{1}, waypts{2}, numRev(1), cBodyInfo, celBodyData, hUiProgDlg);
                eOrbit = [eSMA, eEcc, eInc, eRAAN, eArg, 0, 2*pi, eTA];
                close(hUiProgDlg);

                deltaVVectNTW = zeros(size(deltaVVect));
                for(i=1:size(deltaVVect,2))
                    oIn = orbitsIn(i,:);
                    [rVect,vVect]=getStatefromKepler(oIn(1), oIn(2), oIn(3), oIn(4), oIn(5), oIn(6), wayPtBodies{i+1}.gm);

                    deltaVVectNTW(:,i) = getNTWdvVect(deltaVVect(:,i), rVect, vVect);
                end

                outputs = {wayPtBodies, x, dv, rp, orbitsIn, orbitsOut, xferOrbits, deltaVVect, vInfDNorm, c, vInfDepart, dVDepartVect, dVDepartVectNTW, eRDepartVect, eOrbit, hOrbit, cBodyInfo, deltaVVectNTW, numRev};
                
                outputsStruct.wayPtBodies = wayPtBodies;
                outputsStruct.x = x;
                outputsStruct.rp = rp;
                outputsStruct.orbitsIn = orbitsIn;
                outputsStruct.xferOrbits = xferOrbits;
                outputsStruct.deltaVVect = deltaVVect;
                outputsStruct.vInfDNorm = vInfDNorm;
                outputsStruct.c = c;
                outputsStruct.vInfDepart = vInfDepart;
                outputsStruct.dVDepartVect = dVDepartVect;
                outputsStruct.dVDepartVectNTW = dVDepartVectNTW;
                outputsStruct.eRDepartVect = eRDepartVect;
                outputsStruct.eOrbit = eOrbit;
                outputsStruct.hOrbit = hOrbit;
                outputsStruct.cBodyInfo = cBodyInfo;
                outputsStruct.deltaVVectNTW = deltaVVectNTW;
                outputsStruct.numRev = numRev;
                outputsStruct.celBodyData = celBodyData;
                outputsStruct.vInfArrive = vInfArrive;
                
                setappdata(handles.multiFlybyManSeqGUI,'Output',outputs);
                setappdata(handles.multiFlybyManSeqGUI,'OutputsStruct',outputsStruct);
                
%                 set(handles.dispAxesSelectSlider,'Min',1);
%                 set(handles.dispAxesSelectSlider,'Max',length(wayPtBodies));
%                 set(handles.dispAxesSelectSlider,'Value',1);
%                 set(handles.dispAxesSelectSlider,'SliderStep',ones(1,2)*(1/(length(wayPtBodies)-1)));

                %Set up tabs for plotting
                app.DisplayTabGroup.SelectedTab = app.TransferOrbitsTab;
                tabChildren = app.DisplayTabGroup.Children;
                for(i=3:length(tabChildren))
                    delete(tabChildren(i));
                end
                
                %plot flybys
                plotAllFlybys(app.dispAxesXfer, cBodyInfo, xferOrbits, wayPtBodies, numRev, celBodyData);
                title(app.dispAxesXfer, 'Flyby Trajectories - Global View');
                
                %plot departure
                plotBodyDepartOrbitForFlyby(app.dispAxesDepart, wayPtBodies{1}, eOrbit, hOrbit, dVDepartVect, eRDepartVect);
                app.DepartureOrbitTab.Title = [wayPtBodies{1}.name,' Departure Orbit'];
                
                %plot flybys
                for(i=2:length(wayPtBodies)-1)
                    titleStr = [wayPtBodies{i}.name,' Flyby Orbit'];
                    t = uitab(app.DisplayTabGroup, "Title",titleStr);
                    g = uigridlayout(t, "ColumnWidth","1x", "RowHeight","1x", "ColumnSpacing",5, "RowSpacing",5, "Padding",[5 5 5 5]);
                    hAx = uiaxes(g);
                    
                    plotFlybyTraj(hAx, wayPtBodies{i}, orbitsIn(i-1,:), orbitsOut(i-1,:), deltaVVect(:,i-1), celBodyData);
                end
                
                %Populate text boxes
                [~, OUnitVector, vInfMag] = computeHyperSVectOVect(hOrbit(1), hOrbit(2), hOrbit(3), hOrbit(4), hOrbit(5), 0.0, wayPtBodies{1}.gm);
                OUnitVector = normVector(OUnitVector);

                form = '%9.4f';
                form2 = '%2.9f';
                paddLen = 32;
                printHyperOrbitsMFMSToTextbox(handles.hyperbolicOrbitsText, wayPtBodies, hOrbit, orbitsIn, orbitsOut, vInfArrive, OUnitVector,  vInfMag, form, form2, paddLen);
                printXfrOrbitsMFMSToTextbox(handles.transferOrbitsText,wayPtBodies, xferOrbits, numRev, form, form2, paddLen);
                set(handles.xferOrbitTxtBoxLabel,'String',[cbName,'-Centric Transfer Orbits']);
                printDVManeuversMFMSToTextbox(handles.dvManInfoText,wayPtBodies,dVDepartVectNTW,deltaVVectNTW,eOrbit,orbitsIn,paddLen,form);

                app.saveTextResultsToFileButton.Enable = true;
                app.SaveResultstoFileForLVDButton.Enable = true;    
                
                if(any(c > 0))
                    set(handles.noFeasibleSolLabel,'Visible','on');
                else
                    set(handles.noFeasibleSolLabel,'Visible','off');
                end
            else
                msgbox(errMsg,'Errors were found in multi-flyby manuever sequencer inputs','error');
            end
        end

        % Menu selected function: copyOrbitToClipboardMenu, 
        % ...and 1 other component
        function copyOrbitToClipboardMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to copyOrbitToClipboardMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            celBodyData = getappdata(handles.multiFlybyManSeqGUI,'celBodyData');
            contents = cellstr(get(handles.centralBodyCombo,'String'));
            cbName = contents{get(handles.centralBodyCombo,'Value')};
            cBodyInfo = celBodyData.(lower(strtrim(cbName)));

            copyOrbitToClipboardFromText(handles.eEpochText, handles.eSMAText, handles.eEccText, ...
                handles.eIncText, handles.eRAANText, handles.eArgText, ...
                handles.eMAText, false, cBodyInfo.id);
        end

        % Button pushed function: deleteWayPointRowButton
        function deleteWayPointRowButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to deleteWayPointRowButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            celBodyData = getappdata(handles.multiFlybyManSeqGUI,'celBodyData');

            wayPtData = get(handles.waypointTable,'Data');
            numPreDeleteWayPts = length(wayPtData);
            if(size(wayPtData,1) > 2)
                selected = get(handles.waypointTable,'UserData');
                if(isempty(selected))
                    selected = size(wayPtData);
                end
                if(selected<=length(wayPtData))
                    row = selected(1,1);
                    wayPtData(row,:) = [];

                    bnds = get(handles.flightTimeBndsPanel,'UserData');

                    if(row==1)
                        bnds(1,:) = [];
                    elseif(row == numPreDeleteWayPts)
                        bnds(row-1,:) = [];
                    else
                        preBnds = bnds(1:row-2,:);
                        pstBnds = bnds(row+1:end,:);

                        wayPtBodies = {};
                        for(i=1:length(wayPtData))
                            wayPtBodies{end+1} = celBodyData.(lower(wayPtData{i})); %#ok<AGROW>
                        end
                        [lb, ub] = getMultiFlybyLBUB(0, 1, wayPtBodies, celBodyData);

                        newBnds(1) = lb(row);
                        newBnds(2) = ub(row);
                        newBnds(3) = 0;
                        newBnds(4) = 1E99;

                        if(not(isempty(pstBnds) && not(isempty(preBnds))))
                            bnds = [preBnds; newBnds; pstBnds];
                        elseif(isempty(pstBnds))
                            bnds = [preBnds; newBnds];
                        elseif(isempty(preBnds))
                            bnds = [newBnds; pstBnds];
                        else
                            bnds = newBnds;
                        end
                    end

                    set(handles.flightTimeBndsPanel,'UserData',bnds);
                    set(handles.waypointTable,'Data',wayPtData);
                end
            end
        end

        % Callback function
        function dispAxesSelectSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.dispAxesSelectSlider;

            % hObject    handle to dispAxesSelectSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            celBodyData = getappdata(handles.multiFlybyManSeqGUI,'celBodyData');
            outputs = getappdata(handles.multiFlybyManSeqGUI,'Output');

            if(isempty(outputs))
                return;
            end

            wayPtBodies = outputs{1};
            %                 x = outputs{2};
            %                 dv = outputs{3};
            %                 rp = outputs{4};
            orbitsIn = outputs{5};
            orbitsOut = outputs{6};
            xferOrbits = outputs{7};
            deltaVVect = outputs{8};
            %                 vInfDNorm = outputs{9};
            %                 c = outputs{10};
            %                 vInfDepart = outputs{11};
            dVDepartVect = outputs{12};
            %                 dVDepartVectNTW = outputs{13};
            eRDepartVect = outputs{14};
            eOrbit = outputs{15};
            hOrbit = outputs{16};
            cBodyInfo = outputs{17};
            numRev = outputs{19};

            value = get(hObject,'Value');
            value = round(value);
            set(hObject,'Value',value);

            if(value == 1)
                plotAllFlybys(handles.dispAxesXfer, cBodyInfo, xferOrbits, wayPtBodies, numRev, celBodyData);
                set(handles.axesTitleLabel,'String','Flyby Trajectories - Global View');
            elseif(value == 2)
                plotBodyDepartOrbitForFlyby(handles.dispAxesXfer, wayPtBodies{1}, eOrbit, hOrbit, dVDepartVect, eRDepartVect);
                set(handles.axesTitleLabel,'String',[wayPtBodies{1}.name,' Departure Orbit']);
            elseif(value > 2)
                plotFlybyTraj(handles.dispAxesXfer, wayPtBodies{value-1}, orbitsIn(value-2,:), orbitsOut(value-2,:), deltaVVect(:,value-2), celBodyData);
                set(handles.axesTitleLabel,'String',[wayPtBodies{value-1}.name,' Flyby Orbit']);
            end
        end

        % Value changed function: eArgText
        function eArgText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to eArgText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of eArgText as text
            %        str2double(get(hObject,'String')) returns contents of eArgText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: eEccText
        function eEccText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to eEccText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of eEccText as text
            %        str2double(get(hObject,'String')) returns contents of eEccText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: eEpochText
        function eEpochText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to eEpochText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of eEpochText as text
            %        str2double(get(hObject,'String')) returns contents of eEpochText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: eIncText
        function eIncText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to eIncText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of eIncText as text
            %        str2double(get(hObject,'String')) returns contents of eIncText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: eMAText
        function eMAText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to eMAText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of eMAText as text
            %        str2double(get(hObject,'String')) returns contents of eMAText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: eRAANText
        function eRAANText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to eRAANText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of eRAANText as text
            %        str2double(get(hObject,'String')) returns contents of eRAANText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: eSMAText
        function eSMAText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to eSMAText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of eSMAText as text
            %        str2double(get(hObject,'String')) returns contents of eSMAText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Menu selected function: enterUTAsDateTime, enterUTAsDateTime_1
        function enterUTAsDateTime_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to enterUTAsDateTime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
%             secUT = enterUTAsDateTimeGUI(str2double(get(gco, 'Value')));
            
            output = AppDesignerGUIOutput({-1});
            enterUTAsDateTimeGUI_App(str2double(get(gco, 'Value')), output);
            secUT = output.output{1};
            
            if(secUT >= 0)
                set(gco, 'Value', num2str(secUT));

                evt = matlab.ui.eventdata.ValueChangedData(0, 0);

                launchWindowOpenText_Callback(app, evt);
                launchWindowCloseText_Callback(app, evt);
            end
        end

        % Menu selected function: getOrbitFromKSPActiveVesselMenu, 
        % ...and 1 other component
        function getOrbitFromKSPActiveVesselMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getOrbitFromKSPActiveVesselMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            orbitPanelGetOrbitFromKSPTOTConnectActiveVesselCallBack(handles.eSMAText, handles.eEccText, handles.eIncText, handles.eRAANText, handles.eArgText, handles.eMAText, handles.eEpochText);
        end

        % Menu selected function: getOrbitFromKSP, getOrbitFromKSP_1
        function getOrbitFromKSPTOTConnect_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getOrbitFromSFSFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            orbitPanelGetOrbitFromKSPTOTConnectCallBack(handles.eSMAText, handles.eEccText, handles.eIncText, handles.eRAANText, handles.eArgText, handles.eMAText, handles.eEpochText);
        end

        % Menu selected function: getOrbitFromSFSFile, 
        % ...and 1 other component
        function getOrbitFromSFSFile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getOrbitFromSFSFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            mainGUIHandle = getappdata(handles.multiFlybyManSeqGUI,'MainGUIHandle');
            orbitPanelGetOrbitFromSFSContextCallBack(mainGUIHandle,handles.eSMAText, handles.eEccText, handles.eIncText, handles.eRAANText, handles.eArgText, handles.eMAText, handles.eEpochText);
        end

        % Value changed function: launchWindowCloseText
        function launchWindowCloseText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.launchWindowCloseText;

            % hObject    handle to launchWindowCloseText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of launchWindowCloseText as text
            %        str2double(get(hObject,'String')) returns contents of launchWindowCloseText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            inputSec = str2double(newInput);
            if(checkStrIsNumeric(newInput) && inputSec>=0.0)
                [year, day, hour, minute, sec] = convertSec2YearDayHrMnSec(inputSec);
                dateStr = formDateStr(year, day, hour, minute, sec);
                set(handles.launchWinCloseLabel, 'String', dateStr);
                set(hObject,'String', num2str(inputSec));
            else

            end
        end

        % Value changed function: launchWindowOpenText
        function launchWindowOpenText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.launchWindowOpenText;

            % hObject    handle to launchWindowOpenText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of launchWindowOpenText as text
            %        str2double(get(hObject,'String')) returns contents of launchWindowOpenText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);

            inputSec = str2double(newInput);
            if(checkStrIsNumeric(newInput) && inputSec>=0.0)
                [year, day, hour, minute, sec] = convertSec2YearDayHrMnSec(inputSec);
                dateStr = formDateStr(year, day, hour, minute, sec);
                set(handles.launchWinOpenLabel, 'String', dateStr);
                set(hObject,'String', num2str(inputSec));
            else

            end
        end

        % Value changed function: maxArriveVInfText
        function maxArriveVInfText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to maxArriveVInfText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of maxArriveVInfText as text
            %        str2double(get(hObject,'String')) returns contents of maxArriveVInfText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: maxDepartVInfText
        function maxDepartVInfText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to maxDepartVInfText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of maxDepartVInfText as text
            %        str2double(get(hObject,'String')) returns contents of maxDepartVInfText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: maxFlightTimeText
        function maxFlightTimeText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to maxFlightTimeText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of maxFlightTimeText as text
            %        str2double(get(hObject,'String')) returns contents of maxFlightTimeText as a double
            selected = get(handles.waypointTable,'UserData');
            selected = selected(1);
            bnds = get(handles.flightTimeBndsPanel,'UserData');

            celBodyData = getappdata(handles.multiFlybyManSeqGUI,'celBodyData');

            wayPtBodies = {};
            waypts = get(handles.waypointTable,'Data');
            for(i=1:length(waypts))
                wayPtBodies{end+1} = celBodyData.(lower(waypts{i})); %#ok<AGROW>
            end

            if(selected~=length(wayPtBodies))
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);

                newInputD = str2double(newInput);
                [~, ~, secInDay, ~] = getSecondsInVariousTimeUnits();
                if(checkStrIsNumeric(newInput) && newInputD>=0.0 && newInputD > bnds(selected,1)/secInDay)
                    bnds(selected,2) = newInputD * secInDay;
                    set(handles.flightTimeBndsPanel,'UserData',bnds);
                else
                    set(hObject,'String', num2str(bnds(selected,2)/secInDay));
                    errordlg('Invalid maximum flight time entered.  Make sure what you entered is a number and that it is greater than the minimum shown.','Invalid Maximum Flight Time');
                end
            end
        end

        % Value changed function: maxIterText
        function maxIterText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to maxIterText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of maxIterText as text
            %        str2double(get(hObject,'String')) returns contents of maxIterText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: maxMissionDurationText
        function maxMissionDurationText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to maxMissionDurationText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of maxMissionDurationText as text
            %        str2double(get(hObject,'String')) returns contents of maxMissionDurationText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: maxNumRevsText
        function maxNumRevsText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to maxNumRevsText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of maxNumRevsText as text
            %        str2double(get(hObject,'String')) returns contents of maxNumRevsText as a double
            selected = get(handles.waypointTable,'UserData');
            selected = selected(1);
            bnds = get(handles.flightTimeBndsPanel,'UserData');

            celBodyData = getappdata(handles.multiFlybyManSeqGUI,'celBodyData');

            wayPtBodies = {};
            waypts = get(handles.waypointTable,'Data');
            for(i=1:length(waypts))
                wayPtBodies{end+1} = celBodyData.(lower(waypts{i})); %#ok<AGROW>
            end

            if(selected~=length(wayPtBodies))
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);

                newInputD = str2double(newInput);
                err = validateNumber(newInputD, '', 0, Inf, true, {}, newInput);

                if(checkStrIsNumeric(newInput) && newInputD>=0.0 && isempty(err))
                    bnds(selected,3) = newInputD;
                    set(handles.flightTimeBndsPanel,'UserData',bnds);
                else
                    set(hObject,'String', num2str(bnds(selected,3)));
                    errordlg('Invalid maximum number of revolutions entered.  Make sure what you entered is a positive integer.','Invalid Maximum Revolutions');
                end
            end
        end

        % Value changed function: minCbPeriText
        function minCbPeriText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to minCbPeriText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of minCbPeriText as text
            %        str2double(get(hObject,'String')) returns contents of minCbPeriText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: minFlightTimeText
        function minFlightTimeText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to minFlightTimeText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of minFlightTimeText as text
            %        str2double(get(hObject,'String')) returns contents of minFlightTimeText as a double
            selected = get(handles.waypointTable,'UserData');
            selected = selected(1);
            bnds = get(handles.flightTimeBndsPanel,'UserData');

            celBodyData = getappdata(handles.multiFlybyManSeqGUI,'celBodyData');

            wayPtBodies = {};
            waypts = get(handles.waypointTable,'Data');
            for(i=1:length(waypts))
                wayPtBodies{end+1} = celBodyData.(lower(waypts{i})); %#ok<AGROW>
            end

            if(selected~=length(wayPtBodies))
                newInput = get(hObject,'String');
                newInput = attemptStrEval(newInput);
                set(hObject,'String', newInput);

                newInputD = str2double(newInput);
                [~, ~, secInDay, ~] = getSecondsInVariousTimeUnits();
                if(checkStrIsNumeric(newInput) && newInputD>=0.0 && newInputD < bnds(selected,2)/secInDay)
                    bnds(selected,1) = newInputD * secInDay;
                    set(handles.flightTimeBndsPanel,'UserData',bnds);
                else
                    set(hObject,'String', num2str(bnds(selected,1)/secInDay));
                    errordlg('Invalid minimum flight time entered.  Make sure what you entered is a number and that it is less than the maximum shown.','Invalid Minimum Flight Time');
                end
            end
        end

        % Close request function: multiFlybyManSeqGUI
        function multiFlybyManSeqGUI_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to multiFlybyManSeqGUI (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: delete(hObject) closes the figure
            h = findobj('Name','Genetic Algorithm');

            yesStr = 'Yes';
            button = yesStr;
            if(~isempty(h))
                button = questdlg('The MFMS is still running.  Do you wish to close MFMS?','Close MFMS?','Yes','No','No');
            end

            if(strcmpi(button,yesStr))
                delete(hObject);
                drawnow;
                delete(h);
            end
        end

        % Menu selected function: pasteOrbitFromClipboardMenu, 
        % ...and 1 other component
        function pasteOrbitFromClipboardMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pasteOrbitFromClipboardMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            celBodyData = getappdata(handles.multiFlybyManSeqGUI,'celBodyData');
            pasteOrbitFromClipboard(handles.eEpochText, handles.eSMAText, handles.eEccText, ...
                handles.eIncText, handles.eRAANText, handles.eArgText, ...
                handles.eMAText, false, handles.centralBodyCombo, celBodyData);
        end

        % Value changed function: popSizeText
        function popSizeText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to popSizeText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of popSizeText as text
            %        str2double(get(hObject,'String')) returns contents of popSizeText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Button pushed function: saveTextResultsToFileButton
        function saveTextResultsToFileButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveTextResultsToFileButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            str = cell(0,1);
            str{end+1,1} = get(handles.hypDepAndFlybyOrbitsLabel,'String');
            str{end+1,1} = getHRule();
            str{end+1,1} = get(handles.hyperbolicOrbitsText,'String');
            str{end+1,1} = '\n\n';

            str{end+1,1} = get(handles.xferOrbitTxtBoxLabel,'String');
            str{end+1,1} = getHRule();
            str{end+1,1} = get(handles.transferOrbitsText,'String');
            str{end+1,1} = '\n\n';

            str{end+1,1} = get(handles.dvManInfoLabel,'String');
            str{end+1,1} = getHRule();
            str{end+1,1} = get(handles.dvManInfoText,'String');

            [FileName,PathName,~] = uiputfile('*.txt','Save MFMS Results','MFMS_Results.txt');
            if(FileName ~= 0)
                fid = fopen([PathName,FileName],'wt');
                for(i=1:length(str))
                    if(ischar(str{i}))
                        fprintf(fid,'%s\n',sprintf(str{i}));
                    elseif(iscell(str{i}))
                        substrs = str{i};
                        for(j=1:length(substrs))
                            fprintf(fid,'%s\n',sprintf(substrs{j}));
                        end
                    end
                end
                fclose(fid);
            end
        end

        % Menu selected function: uploadManeuver
        function uploadManeuver_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to uploadManeuver (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            guiData = get(gco,'UserData');
            uploadManeuverToKSP_App(guiData);
        end

        % Cell edit callback: waypointTable
        function waypointTable_CellEditCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to waypointTable (see GCBO)
            % eventdata  structure with the following fields (see UITABLE)
            %	Indices: row and column indices of the cell(s) edited
            %	PreviousData: previous data for the cell(s) edited
            %	EditData: string(s) entered by the user
            %	NewData: EditData or its converted form set on the Data property. Empty if Data was not changed
            %	Error: error string when failed to convert EditData to appropriate value for Data
            % handles    structure with handles and user data (see GUIDATA)
            waypointTable_CellSelectionCallback(app, eventdata);
        end

        % Cell selection callback: waypointTable
        function waypointTable_CellSelectionCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);

            % hObject    handle to waypointTable (see GCBO)
            % eventdata  structure with the following fields (see UITABLE)
            %	Indices: row and column indices of the cell(s) currently selecteds
            % handles    structure with handles and user data (see GUIDATA)
            set(hObject,'UserData',eventdata.Indices);

            celBodyData = getappdata(handles.multiFlybyManSeqGUI,'celBodyData');

            wayPtBodies = {};
            waypts = get(handles.waypointTable,'Data');
            for(i=1:length(waypts))
                wayPtBodies{end+1} = celBodyData.(lower(waypts{i})); %#ok<AGROW>
            end

            selected = eventdata.Indices;
            if(isempty(selected))
                selected = size(wayPtBodies);
            end
            selected = selected(1);

            if(selected==length(wayPtBodies))
                set(handles.minFlightTimeText,'String','N/A');
                set(handles.maxFlightTimeText,'String','N/A');
                set(handles.maxNumRevsText,'String','N/A');
                app.maxDeltaVText.Value = 'N/A';

                set(handles.minFlightTimeText,'Enable','off');
                set(handles.maxFlightTimeText,'Enable','off');
                set(handles.maxNumRevsText,'Enable','off');
                app.maxDeltaVText.Enable = 'off';
            else
                bnds = get(handles.flightTimeBndsPanel,'UserData');
                
                if(selected==1)
                    app.maxDeltaVText.Value = 'N/A';
                    app.maxDeltaVText.Enable = 'off';
                else
                    app.maxDeltaVText.Enable = 'on';
                    app.maxDeltaVText.Value = num2str(bnds(selected,4));
                end
                
                set(handles.minFlightTimeText,'Enable','on');
                set(handles.maxFlightTimeText,'Enable','on');
                set(handles.maxNumRevsText,'Enable','on');
                
                waypt1 = cap1stLetter(wayPtBodies{selected}.name);
                waypt2 = cap1stLetter(wayPtBodies{selected+1}.name);
                set(handles.flightTimeBndsPanel,'Title',[waypt1,' -> ',waypt2,' Flight Time Bounds']);

                [~, ~, secInDay, ~] = getSecondsInVariousTimeUnits();
                set(handles.minFlightTimeText,'String',num2str(bnds(selected,1)/secInDay));
                set(handles.maxFlightTimeText,'String',num2str(bnds(selected,2)/secInDay));
                set(handles.maxNumRevsText,'String',num2str(bnds(selected,3)));
            end
        end

        % Callback function: uitoggletool4
        function uitoggletool4_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.uitoggletool4.State;
            zoomModeObject = zoom(app.multiFlybyManSeqGUI);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Callback function: uitoggletool3
        function uitoggletool3_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.uitoggletool3.State;
            zoomModeObject = zoom(app.multiFlybyManSeqGUI);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Callback function: uitoggletool2
        function uitoggletool2_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable rotation based on the
            % tool's current state.
            state = app.uitoggletool2.State;
            rotate3d(app.multiFlybyManSeqGUI, char(state));
        end

        % Callback function: uitoggletool1
        function uitoggletool1_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool1.State;
            pan(app.multiFlybyManSeqGUI, char(state));
        end

        % Button pushed function: SaveResultstoFileForLVDButton
        function SaveResultstoFileForLVDButtonPushed(app, event)
            mfmsOutputs = getappdata(app.multiFlybyManSeqGUI,'OutputsStruct');
            
            [file,path] = uiputfile(".mat", "Save MFMS Data for LVD Import", "mfmsData.mat");
            if(file ~= 0)
                filename = fullfile(path,file);
                save(filename,'mfmsOutputs');
            end
        end

        % Value changed function: maxDeltaVText
        function maxDeltaVTextValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to minFlightTimeText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of minFlightTimeText as text
            %        str2double(get(hObject,'String')) returns contents of minFlightTimeText as a double
            selected = get(handles.waypointTable,'UserData');
            selected = selected(1);
            bnds = get(handles.flightTimeBndsPanel,'UserData');

            celBodyData = getappdata(handles.multiFlybyManSeqGUI,'celBodyData');

            wayPtBodies = {};
            waypts = get(handles.waypointTable,'Data');
            for(i=1:length(waypts))
                wayPtBodies{end+1} = celBodyData.(lower(waypts{i})); %#ok<AGROW>
            end

            if(selected~=length(wayPtBodies))
                app.maxDeltaVText.Value = attemptStrEval(app.maxDeltaVText.Value);

                newInput = app.maxDeltaVText.Value;
                newInputD = str2double(newInput);
                if(checkStrIsNumeric(newInput) && newInputD>=0.0)
                    bnds(selected,4) = newInputD;
                    set(handles.flightTimeBndsPanel,'UserData',bnds);
                else
                    set(hObject,'String', num2str(bnds(selected,4)));
                    errordlg('Invalid maximum delta-v entered.  Make sure what you entered is a non-negative number.','Invalid Maximum Delta-V');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.multiFlybyManSeqGUI = uifigure('Visible', 'off');
            colormap(app.multiFlybyManSeqGUI, 'parula');
            app.multiFlybyManSeqGUI.Name = 'Multi-Flyby Maneuver Sequencer';
            app.multiFlybyManSeqGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.multiFlybyManSeqGUI.CloseRequestFcn = createCallbackFcn(app, @multiFlybyManSeqGUI_CloseRequestFcn, true);
            app.multiFlybyManSeqGUI.Tag = 'multiFlybyManSeqGUI';
            app.uitoolbar1 = uitoolbar(app.multiFlybyManSeqGUI);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uitoggletool1 = uitoggletool(app.uitoolbar1);
            app.uitoggletool1.Tag = 'uitoggletool1';
            app.uitoggletool1.Tooltip = 'Pan';
            app.uitoggletool1.ClickedCallback = createCallbackFcn(app, @uitoggletool1_ClickedCallback, true);
            app.uitoggletool1.Icon = 'pan_image.png';
            app.uitoggletool2 = uitoggletool(app.uitoolbar1);
            app.uitoggletool2.Tag = 'uitoggletool2';
            app.uitoggletool2.Tooltip = 'Rotate 3D';
            app.uitoggletool2.ClickedCallback = createCallbackFcn(app, @uitoggletool2_ClickedCallback, true);
            app.uitoggletool2.Icon = '3d_rotate.png';
            app.uitoggletool3 = uitoggletool(app.uitoolbar1);
            app.uitoggletool3.Tag = 'uitoggletool3';
            app.uitoggletool3.Tooltip = 'Zoom Out';
            app.uitoggletool3.ClickedCallback = createCallbackFcn(app, @uitoggletool3_ClickedCallback, true);
            app.uitoggletool3.Icon = 'zoom-out.png';
            app.uitoggletool4 = uitoggletool(app.uitoolbar1);
            app.uitoggletool4.Tag = 'uitoggletool4';
            app.uitoggletool4.Tooltip = 'Zoom In';
            app.uitoggletool4.ClickedCallback = createCallbackFcn(app, @uitoggletool4_ClickedCallback, true);
            app.uitoggletool4.Icon = 'zoom-in(1).png';
            app.GridLayout26 = uigridlayout(app.multiFlybyManSeqGUI);
            app.GridLayout = uigridlayout(app.GridLayout26);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.uipanel2 = uipanel(app.GridLayout2);
            app.uipanel2.Title = 'General Parameters';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout3 = uigridlayout(app.uipanel2);
            app.centralBodyCombo = uidropdown(app.GridLayout3);
            app.centralBodyCombo.Items = {'Pop-up Menu'};
            app.centralBodyCombo.ValueChangedFcn = createCallbackFcn(app, @centralBodyCombo_Callback, true);
            app.centralBodyCombo.Tag = 'centralBodyCombo';
            app.centralBodyCombo.Tooltip = 'This is the body about which the Initial Orbit is about.';
            app.centralBodyCombo.Value = 'Pop-up Menu';
            app.text2 = uilabel(app.GridLayout3);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Launch Window Open';
            app.launchWinOpenLabel = uilabel(app.GridLayout3);
            app.launchWinOpenLabel.Tag = 'launchWinOpenLabel';
            app.launchWinOpenLabel.Text = 'Date';
            app.text5 = uilabel(app.GridLayout3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Launch Window Close';
            app.launchWinCloseLabel = uilabel(app.GridLayout3);
            app.launchWinCloseLabel.Tag = 'launchWinCloseLabel';
            app.launchWinCloseLabel.Text = 'Date';
            app.text40 = uilabel(app.GridLayout3);
            app.text40.Tag = 'text40';
            app.text40.Text = 'Number of Runs';
            app.numOfRunsText = uieditfield(app.GridLayout3, 'text');
            app.numOfRunsText.Tag = 'numOfRunsText';
            app.numOfRunsText.Tooltip = 'Setting this input to an integer greater than 1 will instruct MFMS to run that many iterations of the trajectory analysis and return with the DV-optimal result across all runs.  Useful for a hands-off approach to finding tricky flyby sequences.';
            app.numOfRunsText.Value = '1';
            app.text48 = uilabel(app.GridLayout3);
            app.text48.Tag = 'text48';
            app.text48.Tooltip = 'Height, above the atmosphere of the Central Body, to restrict body-to-body orbit periapsises.  Must be greater than or equal to 0.0 km and less than or equal to the lowest periapsis of the waypoint bodies.';
            app.text48.Text = 'Min. CB Periapsis Height';
            app.text54 = uilabel(app.GridLayout3);
            app.text54.Tag = 'text54';
            app.text54.Tooltip = 'Height, above the atmosphere of the Central Body, to restrict body-to-body orbit periapsises.  Must be greater than or equal to 0.0 km and less than or equal to the lowest periapsis of the waypoint bodies.';
            app.text54.Text = 'Maximum Mission Duration';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.text3 = uilabel(app.GridLayout4);
            app.text3.Tag = 'text3';
            app.text3.Text = 'sec';
            app.launchWindowOpenText = uieditfield(app.GridLayout4, 'text');
            app.launchWindowOpenText.ValueChangedFcn = createCallbackFcn(app, @launchWindowOpenText_Callback, true);
            app.launchWindowOpenText.Tag = 'launchWindowOpenText';
            app.launchWindowOpenText.Tooltip = 'Seconds from game start (right-click to enter as date)';
            app.launchWindowOpenText.Value = '0.0';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.text6 = uilabel(app.GridLayout5);
            app.text6.Tag = 'text6';
            app.text6.Text = 'sec';
            app.launchWindowCloseText = uieditfield(app.GridLayout5, 'text');
            app.launchWindowCloseText.ValueChangedFcn = createCallbackFcn(app, @launchWindowCloseText_Callback, true);
            app.launchWindowCloseText.Tag = 'launchWindowCloseText';
            app.launchWindowCloseText.Tooltip = 'Seconds from game start (right-click to enter as date)';
            app.launchWindowCloseText.Value = '157680000';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.text49 = uilabel(app.GridLayout6);
            app.text49.Tag = 'text49';
            app.text49.Text = 'km';
            app.minCbPeriText = uieditfield(app.GridLayout6, 'text');
            app.minCbPeriText.ValueChangedFcn = createCallbackFcn(app, @minCbPeriText_Callback, true);
            app.minCbPeriText.Tag = 'minCbPeriText';
            app.minCbPeriText.Tooltip = 'Height, above the atmosphere of the Central Body, to restrict body-to-body orbit periapsises.  Must be greater than or equal to 0.0 km and less than or equal to the lowest periapsis of the waypoint bodies.';
            app.minCbPeriText.Value = '0.0';
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.text55 = uilabel(app.GridLayout7);
            app.text55.Tag = 'text55';
            app.text55.Text = 'days';
            app.maxMissionDurationText = uieditfield(app.GridLayout7, 'text');
            app.maxMissionDurationText.ValueChangedFcn = createCallbackFcn(app, @maxMissionDurationText_Callback, true);
            app.maxMissionDurationText.Tag = 'maxMissionDurationText';
            app.maxMissionDurationText.Tooltip = 'Maximum mission duration in days (where days is defined by the selected time system).  Enter Inf for no limit.';
            app.maxMissionDurationText.Value = 'Inf';
            app.text1 = uilabel(app.GridLayout3);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Central Body';
            app.flightTimeBndsPanel = uipanel(app.GridLayout2);
            app.flightTimeBndsPanel.Title = 'BODY1 -> BODY2 Flight Time Bounds';
            app.flightTimeBndsPanel.Tag = 'flightTimeBndsPanel';
            app.GridLayout10 = uigridlayout(app.flightTimeBndsPanel);
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.text36 = uilabel(app.GridLayout11);
            app.text36.Tag = 'text36';
            app.text36.Text = 'Minimum';
            app.text37 = uilabel(app.GridLayout11);
            app.text37.Tag = 'text37';
            app.text37.Text = 'Maximum';
            app.GridLayout12 = uigridlayout(app.GridLayout10);
            app.minFlightTimeText = uieditfield(app.GridLayout12, 'text');
            app.minFlightTimeText.ValueChangedFcn = createCallbackFcn(app, @minFlightTimeText_Callback, true);
            app.minFlightTimeText.Tag = 'minFlightTimeText';
            app.minFlightTimeText.Value = '1';
            app.maxFlightTimeText = uieditfield(app.GridLayout12, 'text');
            app.maxFlightTimeText.ValueChangedFcn = createCallbackFcn(app, @maxFlightTimeText_Callback, true);
            app.maxFlightTimeText.Tag = 'maxFlightTimeText';
            app.maxFlightTimeText.Value = '100';
            app.GridLayout13 = uigridlayout(app.GridLayout10);
            app.text38 = uilabel(app.GridLayout13);
            app.text38.Tag = 'text38';
            app.text38.Text = 'days';
            app.text39 = uilabel(app.GridLayout13);
            app.text39.Tag = 'text39';
            app.text39.Text = 'days';
            app.maxNumRevsText = uieditfield(app.GridLayout10, 'text');
            app.maxNumRevsText.ValueChangedFcn = createCallbackFcn(app, @maxNumRevsText_Callback, true);
            app.maxNumRevsText.Tag = 'maxNumRevsText';
            app.maxNumRevsText.Tooltip = 'Maximum number of revolutions the solver will go around the central body before arriving at the next waypoint.  Must be an integer greater than or equal to zero.';
            app.maxNumRevsText.Value = '0';
            app.text47 = uilabel(app.GridLayout10);
            app.text47.Tag = 'text47';
            app.text47.Tooltip = 'Maximum number of revolutions the solver will go around the central body before arriving at the next waypoint.  Must be an integer greater than or equal to zero.';
            app.text47.Text = 'Max. Num. Revolutions';
            app.text47_2 = uilabel(app.GridLayout10);
            app.text47_2.Tag = 'text47';
            app.text47_2.Tooltip = 'Maximum number of revolutions the solver will go around the central body before arriving at the next waypoint.  Must be an integer greater than or equal to zero.';
            app.text47_2.Text = 'Max. Delta-v @ Segment Start';
            app.GridLayout25 = uigridlayout(app.GridLayout10);
            app.text49_2 = uilabel(app.GridLayout25);
            app.text49_2.Tag = 'text49';
            app.text49_2.Text = 'km/s';
            app.maxDeltaVText = uieditfield(app.GridLayout25, 'text');
            app.maxDeltaVText.ValueChangedFcn = createCallbackFcn(app, @maxDeltaVTextValueChanged, true);
            app.maxDeltaVText.Tag = 'maxDeltaVText';
            app.maxDeltaVText.Tooltip = {'Maximum delta-v at the start of this segment.  This is the maximum delta-v allowed at the powered flyby manuever.  Only applies for powered flybys and not the departure maneuver.'};
            app.maxDeltaVText.Value = '0';
            app.iniEllipOrbitPanel = uipanel(app.GridLayout2);
            app.iniEllipOrbitPanel.Title = 'Initial Elliptical Orbit Info';
            app.iniEllipOrbitPanel.Tag = 'iniEllipOrbitPanel';
            app.GridLayout14 = uigridlayout(app.iniEllipOrbitPanel);
            app.text10 = uilabel(app.GridLayout14);
            app.text10.Tag = 'text10';
            app.text10.Text = 'km';
            app.eSMAText = uieditfield(app.GridLayout14, 'text');
            app.eSMAText.ValueChangedFcn = createCallbackFcn(app, @eSMAText_Callback, true);
            app.eSMAText.Tag = 'eSMAText';
            app.eSMAText.Tooltip = 'Semi-major axis of initial orbit.';
            app.eSMAText.Value = '800';
            app.text14 = uilabel(app.GridLayout14);
            app.text14.Tag = 'text14';
            app.text14.Text = 'SMA';
            app.text15 = uilabel(app.GridLayout14);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Ecc.';
            app.text16 = uilabel(app.GridLayout14);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Inc.';
            app.text17 = uilabel(app.GridLayout14);
            app.text17.Tag = 'text17';
            app.text17.Text = 'RAAN';
            app.text18 = uilabel(app.GridLayout14);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Arg. Peri.';
            app.text42 = uilabel(app.GridLayout14);
            app.text42.Tag = 'text42';
            app.text42.Tooltip = 'Mean Anomaly at Epoch';
            app.text42.Text = 'Mean Anomaly';
            app.text46 = uilabel(app.GridLayout14);
            app.text46.Tag = 'text46';
            app.text46.Text = 'Epoch';
            app.eEccText = uieditfield(app.GridLayout14, 'text');
            app.eEccText.ValueChangedFcn = createCallbackFcn(app, @eEccText_Callback, true);
            app.eEccText.Tag = 'eEccText';
            app.eEccText.Tooltip = 'Eccentricity of initial orbit.';
            app.eEccText.Value = '0.0';
            app.eIncText = uieditfield(app.GridLayout14, 'text');
            app.eIncText.ValueChangedFcn = createCallbackFcn(app, @eIncText_Callback, true);
            app.eIncText.Tag = 'eIncText';
            app.eIncText.Tooltip = 'Inclination of initial orbit.';
            app.eIncText.Value = '0.0';
            app.eRAANText = uieditfield(app.GridLayout14, 'text');
            app.eRAANText.ValueChangedFcn = createCallbackFcn(app, @eRAANText_Callback, true);
            app.eRAANText.Tag = 'eRAANText';
            app.eRAANText.Tooltip = 'Right ascension of the ascending node of initial orbit.';
            app.eRAANText.Value = '0.0';
            app.eArgText = uieditfield(app.GridLayout14, 'text');
            app.eArgText.ValueChangedFcn = createCallbackFcn(app, @eArgText_Callback, true);
            app.eArgText.Tag = 'eArgText';
            app.eArgText.Tooltip = 'Argument of periapse of initial orbit.';
            app.eArgText.Value = '0.0';
            app.eMAText = uieditfield(app.GridLayout14, 'text');
            app.eMAText.ValueChangedFcn = createCallbackFcn(app, @eMAText_Callback, true);
            app.eMAText.Tag = 'eMAText';
            app.eMAText.Tooltip = 'Mean Anomaly at epoch of initial orbit.  Leave at 0.0 if you don''t need operational burn targeting.';
            app.eMAText.Value = '0.0';
            app.eEpochText = uieditfield(app.GridLayout14, 'text');
            app.eEpochText.ValueChangedFcn = createCallbackFcn(app, @eEpochText_Callback, true);
            app.eEpochText.Tag = 'eEpochText';
            app.eEpochText.Tooltip = 'Epoch of the initial orbit.  Leave at 0.0 if you don''t need operational burn targeting.';
            app.eEpochText.Value = '0.0';
            app.text11 = uilabel(app.GridLayout14);
            app.text11.Tag = 'text11';
            app.text11.Text = 'deg';
            app.text12 = uilabel(app.GridLayout14);
            app.text12.Tag = 'text12';
            app.text12.Text = 'deg';
            app.text13 = uilabel(app.GridLayout14);
            app.text13.Tag = 'text13';
            app.text13.Text = 'deg';
            app.text41 = uilabel(app.GridLayout14);
            app.text41.Tag = 'text41';
            app.text41.Text = 'deg';
            app.text45 = uilabel(app.GridLayout14);
            app.text45.Tag = 'text45';
            app.text45.Text = 'sec';
            app.text45_2 = uilabel(app.GridLayout14);
            app.text45_2.Tag = 'text45';
            app.text45_2.Text = '';
            app.uipanel8 = uipanel(app.GridLayout2);
            app.uipanel8.Title = 'Waypoints';
            app.uipanel8.Tag = 'uipanel8';
            app.GridLayout8 = uigridlayout(app.uipanel8);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.addWayPointRowButton = uibutton(app.GridLayout9, 'push');
            app.addWayPointRowButton.ButtonPushedFcn = createCallbackFcn(app, @addWayPointRowButton_Callback, true);
            app.addWayPointRowButton.Tag = 'addWayPointRowButton';
            app.addWayPointRowButton.Text = '+';
            app.deleteWayPointRowButton = uibutton(app.GridLayout9, 'push');
            app.deleteWayPointRowButton.ButtonPushedFcn = createCallbackFcn(app, @deleteWayPointRowButton_Callback, true);
            app.deleteWayPointRowButton.Tag = 'deleteWayPointRowButton';
            app.deleteWayPointRowButton.Text = '-';
            app.waypointTable = uitable(app.GridLayout8);
            app.waypointTable.ColumnEditable = true;
            app.waypointTable.CellEditCallback = createCallbackFcn(app, @waypointTable_CellEditCallback, true);
            app.waypointTable.CellSelectionCallback = createCallbackFcn(app, @waypointTable_CellSelectionCallback, true);
            app.waypointTable.Tag = 'waypointTable';
            app.GridLayout15 = uigridlayout(app.GridLayout);
            app.noFeasibleSolLabel = uilabel(app.GridLayout15);
            app.noFeasibleSolLabel.Tag = 'noFeasibleSolLabel';
            app.noFeasibleSolLabel.Visible = 'off';
            app.noFeasibleSolLabel.Text = 'Solution Shown May Not Be Feasible - Please Try Again';
            app.DisplayTabGroup = uitabgroup(app.GridLayout15);
            app.TransferOrbitsTab = uitab(app.DisplayTabGroup);
            app.TransferOrbitsTab.Title = 'Transfer Orbits';
            app.GridLayout23 = uigridlayout(app.TransferOrbitsTab);
            app.dispAxesXfer = uiaxes(app.GridLayout23);
            app.dispAxesXfer.Tag = 'dispAxesXfer';
            app.DepartureOrbitTab = uitab(app.DisplayTabGroup);
            app.DepartureOrbitTab.Title = 'Departure Orbit';
            app.GridLayout24 = uigridlayout(app.DepartureOrbitTab);
            app.dispAxesDepart = uiaxes(app.GridLayout24);
            zlabel(app.dispAxesDepart, 'Z')
            app.GridLayout16 = uigridlayout(app.GridLayout);
            app.hypDepAndFlybyOrbitsLabel = uilabel(app.GridLayout16);
            app.hypDepAndFlybyOrbitsLabel.Tag = 'hypDepAndFlybyOrbitsLabel';
            app.hypDepAndFlybyOrbitsLabel.Text = 'Hyperbolic Departure & Flyby Orbits';
            app.hyperbolicOrbitsText = uitextarea(app.GridLayout16);
            app.hyperbolicOrbitsText.Tag = 'hyperbolicOrbitsText';
            app.hyperbolicOrbitsText.Value = {'No Flyby Maneuver Sequenced Yet'};
            app.xferOrbitTxtBoxLabel = uilabel(app.GridLayout16);
            app.xferOrbitTxtBoxLabel.Tag = 'xferOrbitTxtBoxLabel';
            app.xferOrbitTxtBoxLabel.Text = 'SUN-Centric Transfer Orbit';
            app.transferOrbitsText = uitextarea(app.GridLayout16);
            app.transferOrbitsText.Tag = 'transferOrbitsText';
            app.transferOrbitsText.Value = {'No Flyby Maneuver Sequenced Yet'};
            app.dvManInfoLabel = uilabel(app.GridLayout16);
            app.dvManInfoLabel.Tag = 'dvManInfoLabel';
            app.dvManInfoLabel.Text = 'DV Maneuver Information';
            app.dvManInfoText = uitextarea(app.GridLayout16);
            app.dvManInfoText.Tag = 'dvManInfoText';
            app.dvManInfoText.Value = {'No Flyby Maneuver Sequenced Yet'};
            app.computeFlybyManSeqButton = uibutton(app.GridLayout16, 'push');
            app.computeFlybyManSeqButton.ButtonPushedFcn = createCallbackFcn(app, @computeFlybyManSeqButton_Callback, true);
            app.computeFlybyManSeqButton.Tag = 'computeFlybyManSeqButton';
            app.computeFlybyManSeqButton.Tooltip = 'Computes the flyby sequence.  Go get coffee, this may take a while.';
            app.computeFlybyManSeqButton.Text = 'Compute Flyby Maneuver Sequence';
            app.uipanel13 = uipanel(app.GridLayout16);
            app.uipanel13.Tag = 'uipanel13';
            app.GridLayout17 = uigridlayout(app.uipanel13);
            app.includeDepartVInfCheckbox = uicheckbox(app.GridLayout17);
            app.includeDepartVInfCheckbox.Tag = 'includeDepartVInfCheckbox';
            app.includeDepartVInfCheckbox.Tooltip = {'When checked, the delta-v required to leave the initial orbit will be computed and factored into the objective function to be minimized.'};
            app.includeDepartVInfCheckbox.Text = 'Include Departure Delta-V in Cost Function?';
            app.includeDepartVInfCheckbox.Value = true;
            app.includeArrivalVInfCheckbox = uicheckbox(app.GridLayout17);
            app.includeArrivalVInfCheckbox.Tag = 'includeArrivalVInfCheckbox';
            app.includeArrivalVInfCheckbox.Tooltip = {'When checked, the hyperbolic excess velocity of the inbound orbit at the final waypoint will be added to the objective function to be minimized.'};
            app.includeArrivalVInfCheckbox.Text = 'Include Arrival Hyp. Excess Velocity (VInf) in Cost Function?';
            app.includeArrivalVInfCheckbox.Value = true;
            app.GridLayout18 = uigridlayout(app.GridLayout17);
            app.text57 = uilabel(app.GridLayout18);
            app.text57.Tag = 'text57';
            app.text57.Text = 'Max. Depart. Hyp. Excess Vel.';
            app.maxDepartVInfText = uieditfield(app.GridLayout18, 'text');
            app.maxDepartVInfText.ValueChangedFcn = createCallbackFcn(app, @maxDepartVInfText_Callback, true);
            app.maxDepartVInfText.Tag = 'maxDepartVInfText';
            app.maxDepartVInfText.Tooltip = 'Maximum allowable value of the hyperbolic excess velocity from the departure body.  Set to Inf to not constrain this value.';
            app.maxDepartVInfText.Value = 'Inf';
            app.text56 = uilabel(app.GridLayout18);
            app.text56.Tag = 'text56';
            app.text56.Text = 'km/s';
            app.GridLayout19 = uigridlayout(app.GridLayout17);
            app.text59 = uilabel(app.GridLayout19);
            app.text59.Tag = 'text59';
            app.text59.Text = 'Max. Arrival. Hyp. Excess Vel.';
            app.maxArriveVInfText = uieditfield(app.GridLayout19, 'text');
            app.maxArriveVInfText.ValueChangedFcn = createCallbackFcn(app, @maxArriveVInfText_Callback, true);
            app.maxArriveVInfText.Tag = 'maxArriveVInfText';
            app.maxArriveVInfText.Tooltip = 'Maximum allowable value of the hyperbolic excess velocity inbound to the final waypoint body.  Set to Inf to not constrain this value.';
            app.maxArriveVInfText.Value = 'Inf';
            app.text58 = uilabel(app.GridLayout19);
            app.text58.Tag = 'text58';
            app.text58.Text = 'km/s';
            app.GridLayout20 = uigridlayout(app.GridLayout17);
            app.text61 = uilabel(app.GridLayout20);
            app.text61.Tag = 'text61';
            app.text61.Text = 'Population Size';
            app.popSizeText = uieditfield(app.GridLayout20, 'text');
            app.popSizeText.ValueChangedFcn = createCallbackFcn(app, @popSizeText_Callback, true);
            app.popSizeText.Tag = 'popSizeText';
            app.popSizeText.Tooltip = 'The number of individuals to use in the genetic algorithm.  More individuals will provide a more thorough seach but will be slower to compute.';
            app.popSizeText.Value = '750';
            app.text60 = uilabel(app.GridLayout20);
            app.text60.Tag = 'text60';
            app.text60.Text = '';
            app.GridLayout21 = uigridlayout(app.GridLayout17);
            app.text63 = uilabel(app.GridLayout21);
            app.text63.Tag = 'text63';
            app.text63.Text = 'Max Iterations';
            app.maxIterText = uieditfield(app.GridLayout21, 'text');
            app.maxIterText.ValueChangedFcn = createCallbackFcn(app, @maxIterText_Callback, true);
            app.maxIterText.Tag = 'maxIterText';
            app.maxIterText.Tooltip = 'The maximum number of iterations the genetic algorithm will use.';
            app.maxIterText.Value = '1000';
            app.text62 = uilabel(app.GridLayout21);
            app.text62.Tag = 'text62';
            app.text62.Text = '';
            app.GridLayout22 = uigridlayout(app.GridLayout16);
            app.saveTextResultsToFileButton = uibutton(app.GridLayout22, 'push');
            app.saveTextResultsToFileButton.ButtonPushedFcn = createCallbackFcn(app, @saveTextResultsToFileButton_Callback, true);
            app.saveTextResultsToFileButton.Tag = 'saveTextResultsToFileButton';
            app.saveTextResultsToFileButton.Enable = 'off';
            app.saveTextResultsToFileButton.Tooltip = 'Saves the results of this run to a text file.';
            app.saveTextResultsToFileButton.Text = 'Save Text Results to File';
            app.SaveResultstoFileForLVDButton = uibutton(app.GridLayout22, 'push');
            app.SaveResultstoFileForLVDButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultstoFileForLVDButtonPushed, true);
            app.SaveResultstoFileForLVDButton.Enable = 'off';
            app.SaveResultstoFileForLVDButton.Tooltip = {'To import the file generated by this button into LVD:'; ''; '1) Open LVD'; '2) File -> New Mission Plan from MFMS Output'; '3) Select the file created with this button.'};
            app.SaveResultstoFileForLVDButton.Text = 'Save Results to File For LVD';
            app.GridLayout27 = uigridlayout(app.GridLayout26);
            app.text2_2 = uilabel(app.GridLayout27);
            app.text2_2.Tag = 'text2';
            app.text2_2.Text = {'KSP Trajectory Optimization Tool'; 'Multi-Flyby Maneuver Sequencer'};
            app.Image = uiimage(app.GridLayout27);
            app.Image.ImageSource = 'mfms_20230323.png';
            app.launchWinContxtMenu = uicontextmenu(app.multiFlybyManSeqGUI);
            app.launchWinContxtMenu.Tag = 'launchWinContxtMenu';
            app.enterUTAsDateTime = uimenu(app.launchWinContxtMenu);
            app.enterUTAsDateTime.MenuSelectedFcn = createCallbackFcn(app, @enterUTAsDateTime_Callback, true);
            app.enterUTAsDateTime.Text = 'Enter UT As Date/Time';
            app.enterUTAsDateTime.Tag = 'enterUTAsDateTime';
            % Assign app.launchWinContxtMenu
            app.launchWindowOpenText.ContextMenu = app.launchWinContxtMenu;
            app.launchWindowCloseText.ContextMenu = app.launchWinContxtMenu;
            app.getOrbitFromKSPFile = uicontextmenu(app.multiFlybyManSeqGUI);
            app.getOrbitFromKSPFile.Tag = 'getOrbitFromKSPFile';
            app.getOrbitFromSFSFile = uimenu(app.getOrbitFromKSPFile);
            app.getOrbitFromSFSFile.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromSFSFile_Callback, true);
            app.getOrbitFromSFSFile.Text = 'Get Orbit From SFS File';
            app.getOrbitFromSFSFile.Tag = 'getOrbitFromSFSFile';
            app.getOrbitFromKSP = uimenu(app.getOrbitFromKSPFile);
            app.getOrbitFromKSP.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromKSPTOTConnect_Callback, true);
            app.getOrbitFromKSP.Text = 'Get Orbit From KSP';
            app.getOrbitFromKSP.Tag = 'getOrbitFromKSP';
            app.getOrbitFromKSPActiveVesselMenu = uimenu(app.getOrbitFromKSPFile);
            app.getOrbitFromKSPActiveVesselMenu.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromKSPActiveVesselMenu_Callback, true);
            app.getOrbitFromKSPActiveVesselMenu.Text = 'Get Orbit From KSP (Active Vessel)';
            app.getOrbitFromKSPActiveVesselMenu.Tag = 'getOrbitFromKSPActiveVesselMenu';
            app.copyOrbitToClipboardMenu = uimenu(app.getOrbitFromKSPFile);
            app.copyOrbitToClipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @copyOrbitToClipboardMenu_Callback, true);
            app.copyOrbitToClipboardMenu.Text = 'Copy Orbit to Clipboard';
            app.copyOrbitToClipboardMenu.Tag = 'copyOrbitToClipboardMenu';
            app.pasteOrbitFromClipboardMenu = uimenu(app.getOrbitFromKSPFile);
            app.pasteOrbitFromClipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @pasteOrbitFromClipboardMenu_Callback, true);
            app.pasteOrbitFromClipboardMenu.Text = 'Paste Orbit From Clipboard';
            app.pasteOrbitFromClipboardMenu.Tag = 'pasteOrbitFromClipboardMenu';
            % Assign app.getOrbitFromKSPFile
            app.iniEllipOrbitPanel.ContextMenu = app.getOrbitFromKSPFile;
            app.eSMAText.ContextMenu = app.getOrbitFromKSPFile;
            app.text14.ContextMenu = app.getOrbitFromKSPFile;
            app.text15.ContextMenu = app.getOrbitFromKSPFile;
            app.text16.ContextMenu = app.getOrbitFromKSPFile;
            app.text17.ContextMenu = app.getOrbitFromKSPFile;
            app.text18.ContextMenu = app.getOrbitFromKSPFile;
            app.text42.ContextMenu = app.getOrbitFromKSPFile;
            app.text46.ContextMenu = app.getOrbitFromKSPFile;
            app.eEccText.ContextMenu = app.getOrbitFromKSPFile;
            app.eIncText.ContextMenu = app.getOrbitFromKSPFile;
            app.eRAANText.ContextMenu = app.getOrbitFromKSPFile;
            app.eArgText.ContextMenu = app.getOrbitFromKSPFile;
            app.eMAText.ContextMenu = app.getOrbitFromKSPFile;
            app.eEpochText.ContextMenu = app.getOrbitFromKSPFile;
            app.text41.ContextMenu = app.getOrbitFromKSPFile;
            app.text45.ContextMenu = app.getOrbitFromKSPFile;
            app.text45_2.ContextMenu = app.getOrbitFromKSPFile;
            app.enterDateTimeAndGetOrbit = uicontextmenu(app.multiFlybyManSeqGUI);
            app.enterDateTimeAndGetOrbit.Tag = 'enterDateTimeAndGetOrbit';
            app.enterUTAsDateTime_1 = uimenu(app.enterDateTimeAndGetOrbit);
            app.enterUTAsDateTime_1.MenuSelectedFcn = createCallbackFcn(app, @enterUTAsDateTime_Callback, true);
            app.enterUTAsDateTime_1.Text = 'Enter UT As Date/Time';
            app.enterUTAsDateTime_1.Tag = 'enterUTAsDateTime_1';
            app.getOrbitFromSFSFile_1 = uimenu(app.enterDateTimeAndGetOrbit);
            app.getOrbitFromSFSFile_1.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromSFSFile_Callback, true);
            app.getOrbitFromSFSFile_1.Text = 'Get Orbit From SFS File';
            app.getOrbitFromSFSFile_1.Tag = 'getOrbitFromSFSFile_1';
            app.getOrbitFromKSP_1 = uimenu(app.enterDateTimeAndGetOrbit);
            app.getOrbitFromKSP_1.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromKSPTOTConnect_Callback, true);
            app.getOrbitFromKSP_1.Text = 'Get Orbit From KSP';
            app.getOrbitFromKSP_1.Tag = 'getOrbitFromKSP_1';
            app.getOrbitFromKSPActiveVesselMenu_1 = uimenu(app.enterDateTimeAndGetOrbit);
            app.getOrbitFromKSPActiveVesselMenu_1.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromKSPActiveVesselMenu_Callback, true);
            app.getOrbitFromKSPActiveVesselMenu_1.Text = 'Get Orbit From KSP (Active Vessel)';
            app.getOrbitFromKSPActiveVesselMenu_1.Tag = 'getOrbitFromKSPActiveVesselMenu_1';
            app.copyOrbitToClipboardMenu_1 = uimenu(app.enterDateTimeAndGetOrbit);
            app.copyOrbitToClipboardMenu_1.MenuSelectedFcn = createCallbackFcn(app, @copyOrbitToClipboardMenu_Callback, true);
            app.copyOrbitToClipboardMenu_1.Text = 'Copy Orbit to Clipboard';
            app.copyOrbitToClipboardMenu_1.Tag = 'copyOrbitToClipboardMenu_1';
            app.pasteOrbitFromClipboardMenu_1 = uimenu(app.enterDateTimeAndGetOrbit);
            app.pasteOrbitFromClipboardMenu_1.MenuSelectedFcn = createCallbackFcn(app, @pasteOrbitFromClipboardMenu_Callback, true);
            app.pasteOrbitFromClipboardMenu_1.Text = 'Paste Orbit From Clipboard';
            app.pasteOrbitFromClipboardMenu_1.Tag = 'pasteOrbitFromClipboardMenu_1';
            app.uploadManeuver2KSP = uicontextmenu(app.multiFlybyManSeqGUI);
            app.uploadManeuver2KSP.Tag = 'uploadManeuver2KSP';
            app.uploadManeuver = uimenu(app.uploadManeuver2KSP);
            app.uploadManeuver.MenuSelectedFcn = createCallbackFcn(app, @uploadManeuver_Callback, true);
            app.uploadManeuver.Text = 'Upload Departure Maneuver to KSP';
            app.uploadManeuver.Tag = 'uploadManeuver';
            % Show the figure after all components are created
            app.multiFlybyManSeqGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = multiFlyByManeuverSequencer_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.multiFlybyManSeqGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)multiFlyByManeuverSequencer_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.multiFlybyManSeqGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.multiFlybyManSeqGUI)
        end
    end
end