classdef newMainGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KSPTrajectoryOptimizationToolUIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadBodiesFromFileMenu          matlab.ui.container.Menu
        CreateNewBodiesFilefromKSPMenu  matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        TimeSystemMenu                  matlab.ui.container.Menu
        UseEarthTimeMenu                matlab.ui.container.Menu
        UseKerbinTimeMenu               matlab.ui.container.Menu
        UseCustomTimeMenu               matlab.ui.container.Menu
        GravitationalParameterMenu      matlab.ui.container.Menu
        UseKSPlikeGMMenu                matlab.ui.container.Menu
        UseKSPlikeGMmMenu               matlab.ui.container.Menu
        ConfigureCelestialBodyStateCacheMenu  matlab.ui.container.Menu
        GenerateCelestialBodyDensityInterpolantsMenu  matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        EditThemesMenu                  matlab.ui.container.Menu
        SetThemeMenu                    matlab.ui.container.Menu
        KSPRealTimeSystemMenu           matlab.ui.container.Menu
        UploadManeuverMenu              matlab.ui.container.Menu
        MCCRealTimeSystemMenu           matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        MiscToolsPanel                  matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        CelestialBodyCatalogLabel       matlab.ui.control.Label
        CelestialBodyCatalogButton      matlab.ui.control.Button
        VehicleSizingPanel              matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        VSTButton                       matlab.ui.control.Button
        VehicleSizingToolLabel          matlab.ui.control.Label
        ManeuverDesignPanel             matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        PorkchopPlotButton              matlab.ui.control.Button
        PorkchopPlotGeneratorLabel      matlab.ui.control.Label
        MEAButton                       matlab.ui.control.Button
        O2BOCButton                     matlab.ui.control.Button
        RMSButton                       matlab.ui.control.Button
        MFMSButton                      matlab.ui.control.Button
        ManeuverExecutionAssistantLabel  matlab.ui.control.Label
        Optimal2BurnOrbitChangeLabel    matlab.ui.control.Label
        RendezvousManeuverSequencerLabel  matlab.ui.control.Label
        MultiFlybyManeuverSequencerLabel  matlab.ui.control.Label
        MissonDesignPanel               matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        LVDButton                       matlab.ui.control.Button
        MissionArchitectButton          matlab.ui.control.Button
        LaunchVehicleDesignerLabel      matlab.ui.control.Label
        MissionArchitectLabel           matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        titleText                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        celBodyData CelestialBodyData  % Description
        openToolWindows(1,:) matlab.ui.Figure
        openPathStr(1,1) string % Description
        appThemer AppThemer % Description
        appOptions % Description
    end
    
    methods (Access = private)
        function setThemeMenuCallback(app, src, evt, theme)
            app.appThemer.selTheme = theme;
            app.appThemer.themeAllOpenApps();
            appThemeData = app.appThemer;
            save('appThemeData.mat','appThemeData');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, celBodyData)
            arguments
                app(1,1) newMainGUI_App
                celBodyData(1,1) CelestialBodyData          
            end
            
            global ksptot_TimeSystem options_UseEarthTimeSystem options_gravParamType GLOBAL_AppThemer; %#ok<GVMIS>

            app.celBodyData = celBodyData;
            app.appOptions = getAppOptionsFromFile();
            
            centerUIFigure(app.KSPTrajectoryOptimizationToolUIFigure);

            releaseInfo = matlabRelease;
            titleWVerStr = sprintf("KSP Trajectory Optimization Tool v%s (%s)",getKSPTOTVersionNumStr(), releaseInfo.Release);
            app.KSPTrajectoryOptimizationToolUIFigure.Name = titleWVerStr;
            app.titleText.Tooltip = titleWVerStr;

            %Handle app theming here
            if(exist('appThemeData.mat','file'))
                load('appThemeData.mat','appThemeData');
                app.appThemer = appThemeData;

            else
                app.appThemer = AppThemer();

                appThemeData = app.appThemer;
                save('appThemeData.mat','appThemeData');
            end
            app.appThemer.themeAllOpenApps();
            GLOBAL_AppThemer = app.appThemer;

            %Handle time system
            ksptot_TimeSystem = getTimeSystemFromConfig(app.appOptions, []);
            if(isempty(ksptot_TimeSystem))
                ksptot_TimeSystem = KSPTOT_TimeSystem.kerbinStock();
            end
            options_UseEarthTimeSystem = strcmpi(ksptot_TimeSystem.system,'earth_stock');

            %Handle gravity type
            options_gravParamType = app.appOptions.ksptot.gravparamtype;
        end

        % Menu selected function: LoadBodiesFromFileMenu
        function LoadBodiesFromFileMenuSelected(app, event)
            if(~isempty(app.openPathStr))
                defaultName = fullfile(app.openPathStr,'bodies.ini');
            else
                defaultName = 'bodies.ini';
            end

            FilterSpec = {'*.ini','KSPTOT Bodies Info Files'};
            [FileName,PathName,~] = uigetfile(FilterSpec,'Select Bodies File to Load',defaultName);
            filePathName = [PathName,FileName];

            if(PathName == 0)
                return
            end
            [celBodyDataFromINI,~,~] = inifile(filePathName,'readall');
            app.celBodyData = processINIBodyInfo(celBodyDataFromINI, true, 'bodyInfo', app.KSPTrajectoryOptimizationToolUIFigure);
            app.celBodyData = CelestialBodyData(app.celBodyData);

            [goodTF, celBodyWarnMsgs] = verifyCelBodyData(app.celBodyData);
            if(not(goodTF))
                msg = {sprintf('Potential issues were found with the loaded celestial body information: \n')};
                for(i=1:length(celBodyWarnMsgs))
                    msg{end+1} = sprintf('\t%s\n', celBodyWarnMsgs{i}); %#ok<AGROW>
                end

                msgbox(msg,'Celestial Body Data Warnings','warn');
            end

            updateAppOptions('ksptot', 'bodiesinifile', filePathName);
            app.openPathStr = PathName;
        end

        % Menu selected function: CreateNewBodiesFilefromKSPMenu
        function CreateNewBodiesFilefromKSPMenuSelected(app, event)
            % h = warndlg('Please make sure KSP is running in the Flight Scene with the KSPTOTConnect plugin loaded before continuing.','Ensure KSP Running','modal');
            
            uialert(app.KSPTrajectoryOptimizationToolUIFigure,'Please make sure KSP is running in the Flight Scene with the KSPTOTConnect plugin loaded before continuing.', ...
                                  'Ensure KSP Running', 'Modal',true, 'Icon','info');
            % uiwait(h);
            [FileName,PathName] = getBodiesINIFileFromKSP();

            if(~isempty(FileName) && ~isempty(PathName))
                helpstring = sprintf('New bodies file created at:\n%s\n\nPlease use the File -> Load Bodies From File menu item to load the new file into KSPTOT.',[PathName,FileName]);
                % helpdlg(helpstring,'Bodies File Created');

                uialert(app.KSPTrajectoryOptimizationToolUIFigure, helpstring, 'Bodies File Created', 'Icon','success');
            end
        end

        % Menu selected function: UseEarthTimeMenu
        function UseEarthTimeMenuSelected(app, event)
            global options_UseEarthTimeSystem ksptot_TimeSystem; %#ok<GVMIS>

            options_UseEarthTimeSystem = true;
            ksptot_TimeSystem = KSPTOT_TimeSystem.earthStock();
            updateAppOptions('ksptot', 'timesystem', 'Earth');
            notify(app.appOptions.ksptot,'TimeSystemUpdated');
        end

        % Menu selected function: UseKerbinTimeMenu
        function UseKerbinTimeMenuSelected(app, event)
            global options_UseEarthTimeSystem ksptot_TimeSystem; %#ok<GVMIS>

            options_UseEarthTimeSystem = false;
            ksptot_TimeSystem = KSPTOT_TimeSystem.kerbinStock();
            updateAppOptions('ksptot', 'timesystem', 'Kerbin');
            notify(app.appOptions.ksptot,'TimeSystemUpdated');
        end

        % Menu selected function: GravitationalParameterMenu
        function GravitationalParameterMenuSelected(app, event)
            global options_gravParamType; %#ok<GVMIS>

            if(strcmpi(options_gravParamType,'kspStockLike'))
                app.UseKSPlikeGMMenu.Checked = true;
                app.UseKSPlikeGMmMenu.Checked = false;
            elseif(strcmpi(options_gravParamType,'rssLike'))
                app.UseKSPlikeGMMenu.Checked = false;
                app.UseKSPlikeGMmMenu.Checked = true;
            end
        end

        % Menu selected function: TimeSystemMenu
        function TimeSystemMenuSelected(app, event)
            global options_UseEarthTimeSystem ksptot_TimeSystem; %#ok<GVMIS>

            if(not(isempty(ksptot_TimeSystem)))
                activeSystem = lower(ksptot_TimeSystem.system);
            elseif(options_UseEarthTimeSystem == true)
                activeSystem = 'earth_stock';
            else
                activeSystem = 'kerbin_stock';
            end

            app.UseEarthTimeMenu.Checked = strcmpi(activeSystem,'earth_stock');
            app.UseKerbinTimeMenu.Checked = strcmpi(activeSystem,'kerbin_stock');

            if(isprop(app,'UseCustomTimeMenu'))
                customTS = getCustomTimeSystem(app.appOptions);
                if(isempty(customTS))
                    app.UseCustomTimeMenu.Visible = 'off';
                else
                    app.UseCustomTimeMenu.Visible = 'on';
                end
                app.UseCustomTimeMenu.Checked = strcmpi(activeSystem,'custom');
            end
        end

        % Menu selected function: ConfigureCelestialBodyStateCacheMenu
        function ConfigureCelestialBodyStateCacheMenuSelected(app, event)
            configureCelBodyStateCacheGUI_App(app.celBodyData);
        end

        % Menu selected function: ViewMenu
        function ViewMenuSelected(app, event)
            themes = app.appThemer.themes;
            delete(app.SetThemeMenu.Children);

            if(numel(themes) > 0)
                for(i=1:numel(themes)) %#ok<*NO4LP>
                    theme = themes(i);

                    menuName = theme.name;

                    callback = @(src,evt) setThemeMenuCallback(app, src, evt, theme);
                    uimenu(app.SetThemeMenu, 'Text',menuName, 'MenuSelectedFcn', callback, 'Tooltip',theme.desc);
                end

            else
                uimenu(handles.openRecentMissionPlansMenu, 'Text','No Recent Missions', 'Enable','off');
            end
        end

        % Menu selected function: EditThemesMenu
        function EditThemesMenuSelected(app, event)
            AppThemeEditorGUI_App(app.appThemer);
        end

        % Menu selected function: UploadManeuverMenu
        function UploadManeuverMenuSelected(app, event)
            uploadManeuverToKSP_App();
        end

        % Menu selected function: MCCRealTimeSystemMenu
        function MCCRealTimeSystemMenuSelected(app, event)
            app.appOptions = getAppOptionsFromFile();
            
            if(isprop(app.appOptions.ksptot,'rtshostname') && ~isempty(app.appOptions.ksptot.rtshostname))
                rtsHost = app.appOptions.ksptot.rtshostname;
            else
                rtsHost = 'localhost';
            end

            hFig = rts_mainGUI(app.celBodyData, rtsHost);

            app.openToolWindows = cleanOpenToolWindowsArr(app.openToolWindows);
            app.openToolWindows(end+1) = hFig;
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            helpAboutGUI_App();
        end

        % Button pushed function: MissionArchitectButton
        function MissionArchitectButtonPushed(app, event)
            ma_hFig = findall(0,'Tag','ma_MainGUI');
            if(~isempty(ma_hFig))
                figure(ma_hFig);
            else
                hMsg = uiprogressdlg(app.KSPTrajectoryOptimizationToolUIFigure, 'Message','Starting Mission Architect.  Please wait...', ...
                                    'Title', 'Mission Architect', ...
                                    'Indeterminate','on', ...
                                    'Icon','ma_20230402_2.png');

                hFig = ma_MainGUI(app.celBodyData);
                app.openToolWindows = cleanOpenToolWindowsArr(app.openToolWindows);
                app.openToolWindows(end+1) = hFig;

                if(isvalid(hMsg))
                    close(hMsg);
                end
            end
        end

        % Button pushed function: LVDButton
        function LVDButtonPushed(app, event)
            lvd_hFig = findall(0,'Tag','ma_LvdMainGUI');
            if(~isempty(lvd_hFig))
                figure(lvd_hFig);
            else
                hMsg = uiprogressdlg(app.KSPTrajectoryOptimizationToolUIFigure, 'Message','Starting Launch Vehicle Designer.  Please wait...', ...
                                    'Title', 'Launch Vehicle Designer', ...
                                    'Indeterminate','on', ...
                                    'Icon','ksptot_logo1_transparent.png');

                try
                    lvdApp = ma_LvdMainGUI_App(app.celBodyData);

                    app.openToolWindows = cleanOpenToolWindowsArr(app.openToolWindows);
                    app.openToolWindows(end+1) = lvdApp.ma_LvdMainGUI;

                    if(isvalid(hMsg))
                        close(hMsg);
                    end
                catch ME
                    if(isvalid(hMsg))
                        close(hMsg);
                    end
                end
            end
        end

        % Button pushed function: MFMSButton
        function MFMSButtonPushed(app, event)
            hMsg = uiprogressdlg(app.KSPTrajectoryOptimizationToolUIFigure, 'Message','Starting Multi-flyby Maneuver Sequencer.  Please wait...', ...
                                'Title', 'Multi-flyby Maneuver Sequencer', ...
                                'Indeterminate','on', ...
                                'Icon','mfms_20230323.png');

            mfmsApp = multiFlyByManeuverSequencer_App(app.celBodyData);

            if(isvalid(hMsg))
                close(hMsg);
            end

            app.openToolWindows = cleanOpenToolWindowsArr(app.openToolWindows);
            app.openToolWindows(end+1) = mfmsApp.multiFlybyManSeqGUI;
        end

        % Callback function
        function MFPorkchopButtonPushed(app, event)
            hMsg = uiprogressdlg(app.KSPTrajectoryOptimizationToolUIFigure, 'Message','Starting Multi-flyby Porkchop Plotter.  Please wait...', ...
                                'Title', 'Multi-flyby Porkchop Plotter', ...
                                'Indeterminate','on');

            mfmsPorkApp = flyByPorkchopPlotterGUI_App(app.celBodyData);

            if(isvalid(hMsg))
                close(hMsg);
            end

            app.openToolWindows = cleanOpenToolWindowsArr(app.openToolWindows);
            app.openToolWindows(end+1) = mfmsPorkApp.flyByPorkchopPlotterGUI;
        end

        % Button pushed function: RMSButton
        function RMSButtonPushed(app, event)
            hMsg = uiprogressdlg(app.KSPTrajectoryOptimizationToolUIFigure, 'Message','Starting Rendezvous Maneuver Sequencer.  Please wait...', ...
                                'Title', 'Rendezvous Maneuver Sequencer', ...
                                'Indeterminate','on', ...
                                'Icon', 'rms_20230327.png');

            rApp = rendezvousGUI_App(app.celBodyData);

            if(isvalid(hMsg))
                close(hMsg);
            end

            app.openToolWindows = cleanOpenToolWindowsArr(app.openToolWindows);
            app.openToolWindows(end+1) = rApp.rendezvousGUI;
        end

        % Button pushed function: O2BOCButton
        function O2BOCButtonPushed(app, event)
            hMsg = uiprogressdlg(app.KSPTrajectoryOptimizationToolUIFigure, 'Message','Starting Optimal 2-Burn Orbit Change.  Please wait...', ...
                                'Title', 'Optimal 2-Burn Orbit Change', ...
                                'Indeterminate','on', ...
                                'Icon','o2boc_20230328.png');

            TBOCApp = OptimalTwoBurnOrbitChange_App(app.celBodyData);

            if(isvalid(hMsg))
                close(hMsg);
            end

            app.openToolWindows = cleanOpenToolWindowsArr(app.openToolWindows);
            app.openToolWindows(end+1) = TBOCApp.OptimalTwoBurnOrbitChangeGUI;
        end

        % Button pushed function: MEAButton
        function MEAButtonPushed(app, event)
            hMsg = uiprogressdlg(app.KSPTrajectoryOptimizationToolUIFigure, 'Message','Starting Maneuver Execution Assistant.  Please wait...', ...
                                'Title', 'Maneuver Execution Assistant', ...
                                'Indeterminate','on', ...
                                'Icon', 'mea_20230327.png');

            meaApp = manExeAssistGUI_App(app.celBodyData);

            if(isvalid(hMsg))
                close(hMsg);
            end

            app.openToolWindows = cleanOpenToolWindowsArr(app.openToolWindows);
            app.openToolWindows(end+1) = meaApp.manExeAssistGUI;
        end

        % Button pushed function: VSTButton
        function VSTButtonPushed(app, event)
            vs_hFig = findall(0,'Tag','vs_mainGUI');
            if(~isempty(vs_hFig))
                figure(vs_hFig);
            else
                hMsg = uiprogressdlg(app.KSPTrajectoryOptimizationToolUIFigure, 'Message','Starting Vehicle Sizing Tool.  Please wait...', ...
                                    'Title', 'Vehicle Sizing Tool', ...
                                    'Indeterminate','on', ...
                                    'Icon', 'vst_20230324.png');

                vsApp = vs_mainGUI_App(app.celBodyData);

                app.openToolWindows = cleanOpenToolWindowsArr(app.openToolWindows);
                app.openToolWindows(end+1) = vsApp.vs_mainGUI;

                if(isvalid(hMsg))
                    close(hMsg);
                end
            end
        end

        % Button pushed function: PorkchopPlotButton
        function PorkchopPlotButtonPushed(app, event)
            porkchop_hFig = findall(0,'Tag','mainGUIFigure');
            if(~isempty(porkchop_hFig))
                figure(porkchop_hFig);
            else
                hMsg = uiprogressdlg(app.KSPTrajectoryOptimizationToolUIFigure, 'Message','Starting Porkchop Generator.  Please wait...', ...
                                    'Title', 'Porkchop Generator', ...
                                    'Indeterminate','on', ...
                                    'Icon','porkchop_gen_20230403.png'); %'Icon', 'vst_20230324.png'

                porkchopApp = mainGUI_App(app.celBodyData);

                app.openToolWindows = cleanOpenToolWindowsArr(app.openToolWindows);
                app.openToolWindows(end+1) = porkchopApp.mainGUIFigure;

                if(isvalid(hMsg))
                    close(hMsg);
                end
            end
        end

        % Close request function: KSPTrajectoryOptimizationToolUIFigure
        function KSPTrajectoryOptimizationToolUIFigureCloseRequest(app, event)
            app.openToolWindows = cleanOpenToolWindowsArr(app.openToolWindows);
            valid = ishghandle(app.openToolWindows);
            if(any(valid))
                qstring = {'The following KSPTOT tools are currently open and will be closed upon closing the main KSPTOT window:',''};
                for(i=1:length(valid)) %#ok<*NO4LP>
                    if(valid(i))
                        qstring{end+1} = app.openToolWindows(i).Name; %#ok<AGROW>
                    end
                end
                % button = questdlg(qstring,'Tool Windows Open','Okay','Cancel','Cancel');
                button = uiconfirm(app.KSPTrajectoryOptimizationToolUIFigure, ...
                                   qstring, 'Tool Windows Open', ...
                                   'Options',{'Okay','Cancel'}, ...
                                   'DefaultOption','Okay', ...
                                   'CancelOption','Cancel', ...
                                   'Icon','question');
                
            else
                button = 'Okay';
            end

            if(strcmpi(button,'Okay'))
                for(i=1:length(valid))
                    if(valid(i))
                        close(app.openToolWindows(i));
                        if(ishghandle(app.openToolWindows(i)))
                            delete(app.openToolWindows(i));
                        end
                    end
                end

                close(app.KSPTrajectoryOptimizationToolUIFigure);
                delete(app);
            else
                return;
            end
        end

        % Callback function
        function CelestialBodyCatalogMenuSelected(app, event)
            ma_CelBodyCatalogGUI_App(app.celBodyData);
        end

        % Button pushed function: CelestialBodyCatalogButton
        function CelestialBodyCatalogButtonPushed(app, event)
            catalog_hFig = findall(0,'Tag','ma_CelBodyCatalogGUI');
            if(~isempty(catalog_hFig))
                figure(catalog_hFig);
            else
                hMsg = uiprogressdlg(app.KSPTrajectoryOptimizationToolUIFigure, 'Message','Starting Celestial Body Catalog.  Please wait...', ...
                                    'Title', 'Celestial Body Catalog', ...
                                    'Indeterminate','on', ...
                                    'Icon', 'celBodyCatalog_20230327_cropped.png');

                cbcApp = ma_CelBodyCatalogGUI_App(app.celBodyData);

                app.openToolWindows = cleanOpenToolWindowsArr(app.openToolWindows);
                app.openToolWindows(end+1) = cbcApp.ma_CelBodyCatalogGUI;

                if(isvalid(hMsg))
                    close(hMsg);
                end
            end
        end

        % Menu selected function: 
        % GenerateCelestialBodyDensityInterpolantsMenu
        function GenerateCelestialBodyDensityInterpolantsMenuSelected(app, event)
            startParallelPool(app.KSPTrajectoryOptimizationToolUIFigure, @(x,y) disp(x), feature('numcores'));
            
            d = uiprogressdlg(app.KSPTrajectoryOptimizationToolUIFigure, "Icon","info", "Title","Computing Atmo Density Interpolants", "ShowPercentage","on");

            bodyInfos = app.celBodyData.getAllBodyInfo();
            for(i=1:length(bodyInfos))
                bodyInfo = bodyInfos(i);
                d.Message = sprintf("Computing Atmospheric Density Interpolant for %s...", bodyInfo.name);

                if(bodyInfo.atmohgt > 0)
                    bodyInfo.createDensityGriddedInterp();
                end
                d.Value = i/length(bodyInfos);
            end

            close(d);  
            
            uialert(app.KSPTrajectoryOptimizationToolUIFigure,"Interpolants for all bodies with atmospheres have been computed.","Interpolants Computed", "Icon","success");
        end

        % Menu selected function: UseCustomTimeMenu
        function UseCustomTimeMenuSelected(app, event)
            global options_UseEarthTimeSystem ksptot_TimeSystem; %#ok<GVMIS>

            customTS = getCustomTimeSystem(app.appOptions);
            if(isempty(customTS))
                uialert(app.KSPTrajectoryOptimizationToolUIFigure, ...
                    'No custom time system is defined.  Add a [Time] section to your bodies.ini file or create a time.ini file to enable a custom time system.', ...
                    'No Custom Time System', 'Icon','warning');
                return;
            end

            ksptot_TimeSystem = customTS;
            options_UseEarthTimeSystem = strcmpi(customTS.system,'earth_stock');
            updateAppOptions('ksptot', 'timesystem', 'Custom');
            notify(app.appOptions.ksptot,'TimeSystemUpdated');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KSPTrajectoryOptimizationToolUIFigure = uifigure('Visible', 'off');
            app.KSPTrajectoryOptimizationToolUIFigure.Name = 'KSP Trajectory Optimization Tool';
            app.KSPTrajectoryOptimizationToolUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.KSPTrajectoryOptimizationToolUIFigure.CloseRequestFcn = createCallbackFcn(app, @KSPTrajectoryOptimizationToolUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.KSPTrajectoryOptimizationToolUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadBodiesFromFileMenu = uimenu(app.FileMenu);
            app.LoadBodiesFromFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadBodiesFromFileMenuSelected, true);
            app.LoadBodiesFromFileMenu.Text = 'Load Bodies From File';
            app.CreateNewBodiesFilefromKSPMenu = uimenu(app.FileMenu);
            app.CreateNewBodiesFilefromKSPMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateNewBodiesFilefromKSPMenuSelected, true);
            app.CreateNewBodiesFilefromKSPMenu.Text = 'Create New Bodies File from KSP';
            app.EditMenu = uimenu(app.KSPTrajectoryOptimizationToolUIFigure);
            app.EditMenu.Text = 'Edit';
            app.TimeSystemMenu = uimenu(app.EditMenu);
            app.TimeSystemMenu.MenuSelectedFcn = createCallbackFcn(app, @TimeSystemMenuSelected, true);
            app.TimeSystemMenu.Text = 'Time System';
            app.UseEarthTimeMenu = uimenu(app.TimeSystemMenu);
            app.UseEarthTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @UseEarthTimeMenuSelected, true);
            app.UseEarthTimeMenu.Text = 'Use Earth Time';
            app.UseKerbinTimeMenu = uimenu(app.TimeSystemMenu);
            app.UseKerbinTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @UseKerbinTimeMenuSelected, true);
            app.UseKerbinTimeMenu.Text = 'Use Kerbin Time';
            app.UseCustomTimeMenu = uimenu(app.TimeSystemMenu);
            app.UseCustomTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @UseCustomTimeMenuSelected, true);
            app.UseCustomTimeMenu.Text = 'Use Custom Time';
            app.GravitationalParameterMenu = uimenu(app.EditMenu);
            app.GravitationalParameterMenu.MenuSelectedFcn = createCallbackFcn(app, @GravitationalParameterMenuSelected, true);
            app.GravitationalParameterMenu.Text = 'Gravitational Parameter';
            app.UseKSPlikeGMMenu = uimenu(app.GravitationalParameterMenu);
            app.UseKSPlikeGMMenu.Text = 'Use KSP-like GM';
            app.UseKSPlikeGMmMenu = uimenu(app.GravitationalParameterMenu);
            app.UseKSPlikeGMmMenu.Text = 'Use KSP-like G(M+m)';
            app.ConfigureCelestialBodyStateCacheMenu = uimenu(app.EditMenu);
            app.ConfigureCelestialBodyStateCacheMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfigureCelestialBodyStateCacheMenuSelected, true);
            app.ConfigureCelestialBodyStateCacheMenu.Separator = 'on';
            app.ConfigureCelestialBodyStateCacheMenu.Text = 'Configure Celestial Body State Cache';
            app.GenerateCelestialBodyDensityInterpolantsMenu = uimenu(app.EditMenu);
            app.GenerateCelestialBodyDensityInterpolantsMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerateCelestialBodyDensityInterpolantsMenuSelected, true);
            app.GenerateCelestialBodyDensityInterpolantsMenu.Tooltip = {'Computing the density of a celestial body directly from the atmosphere models can be slow.  Creating an interpolant can drastically speed up this calculation, at the cost of some fidelity.'};
            app.GenerateCelestialBodyDensityInterpolantsMenu.Text = 'Generate Celestial Body Density Interpolants';
            app.ViewMenu = uimenu(app.KSPTrajectoryOptimizationToolUIFigure);
            app.ViewMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.ViewMenu.Text = 'View';
            app.EditThemesMenu = uimenu(app.ViewMenu);
            app.EditThemesMenu.MenuSelectedFcn = createCallbackFcn(app, @EditThemesMenuSelected, true);
            app.EditThemesMenu.Tooltip = {'The general color scheme of KSPTOT can be set and edited through the use of "themes".  Edit those themes here.'};
            app.EditThemesMenu.Text = 'Edit Themes';
            app.SetThemeMenu = uimenu(app.ViewMenu);
            app.SetThemeMenu.Text = 'Set Theme';
            app.KSPRealTimeSystemMenu = uimenu(app.KSPTrajectoryOptimizationToolUIFigure);
            app.KSPRealTimeSystemMenu.Text = 'KSP Real Time System';
            app.UploadManeuverMenu = uimenu(app.KSPRealTimeSystemMenu);
            app.UploadManeuverMenu.MenuSelectedFcn = createCallbackFcn(app, @UploadManeuverMenuSelected, true);
            app.UploadManeuverMenu.Text = 'Upload Maneuver';
            app.MCCRealTimeSystemMenu = uimenu(app.KSPRealTimeSystemMenu);
            app.MCCRealTimeSystemMenu.MenuSelectedFcn = createCallbackFcn(app, @MCCRealTimeSystemMenuSelected, true);
            app.MCCRealTimeSystemMenu.Text = 'MCC Real Time System';
            app.HelpMenu = uimenu(app.KSPTrajectoryOptimizationToolUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.KSPTrajectoryOptimizationToolUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.titleText = uilabel(app.GridLayout2);
            app.titleText.Tag = 'titleText';
            app.titleText.Text = {'KSP Trajectory Optimization Tool'; ''};
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = 'ksptot_logo4_transparent.png';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.MissonDesignPanel = uipanel(app.GridLayout3);
            app.MissonDesignPanel.Title = 'Misson Design';
            app.GridLayout4 = uigridlayout(app.MissonDesignPanel);
            app.MissionArchitectLabel = uilabel(app.GridLayout4);
            app.MissionArchitectLabel.Text = 'Mission Architect';
            app.LaunchVehicleDesignerLabel = uilabel(app.GridLayout4);
            app.LaunchVehicleDesignerLabel.Text = 'Launch Vehicle Designer';
            app.MissionArchitectButton = uibutton(app.GridLayout4, 'push');
            app.MissionArchitectButton.ButtonPushedFcn = createCallbackFcn(app, @MissionArchitectButtonPushed, true);
            app.MissionArchitectButton.Icon = 'ma_20230402_2.png';
            app.MissionArchitectButton.Tooltip = {'Designs and optimizes in-space mission trajectories.  Only use for simple missions with impulsive delta-v maneuvers.  More complex mission design, including gravity assist missions and launch vehicle powered ascent, should be done in Launch Vehicle Designer.'};
            app.MissionArchitectButton.Text = '';
            app.LVDButton = uibutton(app.GridLayout4, 'push');
            app.LVDButton.ButtonPushedFcn = createCallbackFcn(app, @LVDButtonPushed, true);
            app.LVDButton.Icon = 'ksptot_logo1_transparent.png';
            app.LVDButton.Tooltip = {'Designs and optimizes higher fidelity, complex spacecraft and launch vehicle mission trajectories.  Can perform launch vehicle powered ascent, gravity assist missions, n-body gravity missions, and more.  Can also model ground stations; sensors; and vehicle tanks, engines, electric power, and more.'};
            app.LVDButton.Text = '';
            app.ManeuverDesignPanel = uipanel(app.GridLayout3);
            app.ManeuverDesignPanel.Title = 'Maneuver Design';
            app.GridLayout5 = uigridlayout(app.ManeuverDesignPanel);
            app.MultiFlybyManeuverSequencerLabel = uilabel(app.GridLayout5);
            app.MultiFlybyManeuverSequencerLabel.Text = 'Multi-Flyby Maneuver Sequencer';
            app.RendezvousManeuverSequencerLabel = uilabel(app.GridLayout5);
            app.RendezvousManeuverSequencerLabel.Text = 'Rendezvous Maneuver Sequencer';
            app.Optimal2BurnOrbitChangeLabel = uilabel(app.GridLayout5);
            app.Optimal2BurnOrbitChangeLabel.Text = 'Optimal 2-Burn Orbit Change';
            app.ManeuverExecutionAssistantLabel = uilabel(app.GridLayout5);
            app.ManeuverExecutionAssistantLabel.Text = 'Maneuver Execution Assistant';
            app.MFMSButton = uibutton(app.GridLayout5, 'push');
            app.MFMSButton.ButtonPushedFcn = createCallbackFcn(app, @MFMSButtonPushed, true);
            app.MFMSButton.Icon = 'mfms_20230323.png';
            app.MFMSButton.Tooltip = {'Plans low fidelity gravity assist trajectories from and to use-defined celestial bodies.'};
            app.MFMSButton.Text = '';
            app.RMSButton = uibutton(app.GridLayout5, 'push');
            app.RMSButton.ButtonPushedFcn = createCallbackFcn(app, @RMSButtonPushed, true);
            app.RMSButton.Icon = 'rms_20230327.png';
            app.RMSButton.Tooltip = {'Computes delta-v and/or time-optimal rendezvous maneuvers between two orbits.'};
            app.RMSButton.Text = '';
            app.O2BOCButton = uibutton(app.GridLayout5, 'push');
            app.O2BOCButton.ButtonPushedFcn = createCallbackFcn(app, @O2BOCButtonPushed, true);
            app.O2BOCButton.Icon = 'o2boc_20230328.png';
            app.O2BOCButton.Tooltip = {'Computes delta-v optimal maneuvers to change a given orbit to another around a set celestial body.'};
            app.O2BOCButton.Text = '';
            app.MEAButton = uibutton(app.GridLayout5, 'push');
            app.MEAButton.ButtonPushedFcn = createCallbackFcn(app, @MEAButtonPushed, true);
            app.MEAButton.Icon = 'mea_20230327.png';
            app.MEAButton.Tooltip = {'Provides burn information for a given burn occuring in a particular orbit.'};
            app.MEAButton.Text = '';
            app.PorkchopPlotGeneratorLabel = uilabel(app.GridLayout5);
            app.PorkchopPlotGeneratorLabel.Text = 'Porkchop Plot Generator';
            app.PorkchopPlotButton = uibutton(app.GridLayout5, 'push');
            app.PorkchopPlotButton.ButtonPushedFcn = createCallbackFcn(app, @PorkchopPlotButtonPushed, true);
            app.PorkchopPlotButton.Icon = 'porkchop_gen_20230403.png';
            app.PorkchopPlotButton.Tooltip = {'Generates porkchop plots and simple ballistic transfers.'};
            app.PorkchopPlotButton.Text = '';
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.VehicleSizingPanel = uipanel(app.GridLayout7);
            app.VehicleSizingPanel.Title = 'Vehicle Sizing';
            app.GridLayout6 = uigridlayout(app.VehicleSizingPanel);
            app.VehicleSizingToolLabel = uilabel(app.GridLayout6);
            app.VehicleSizingToolLabel.Text = 'Vehicle Sizing Tool';
            app.VSTButton = uibutton(app.GridLayout6, 'push');
            app.VSTButton.ButtonPushedFcn = createCallbackFcn(app, @VSTButtonPushed, true);
            app.VSTButton.Icon = 'vst_20230324.png';
            app.VSTButton.Tooltip = {'Provides vehicle sizing information (tank/delta-v estimates) for a given mission defintion, delta-v, and number of stages per phase.'};
            app.VSTButton.Text = '';
            app.MiscToolsPanel = uipanel(app.GridLayout7);
            app.MiscToolsPanel.Title = 'Misc Tools';
            app.GridLayout8 = uigridlayout(app.MiscToolsPanel);
            app.CelestialBodyCatalogButton = uibutton(app.GridLayout8, 'push');
            app.CelestialBodyCatalogButton.ButtonPushedFcn = createCallbackFcn(app, @CelestialBodyCatalogButtonPushed, true);
            app.CelestialBodyCatalogButton.Icon = 'celBodyCatalog_20230327_cropped.png';
            app.CelestialBodyCatalogButton.Text = '';
            app.CelestialBodyCatalogLabel = uilabel(app.GridLayout8);
            app.CelestialBodyCatalogLabel.Text = 'Celestial Body Catalog';
            % Show the figure after all components are created
            app.KSPTrajectoryOptimizationToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newMainGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.KSPTrajectoryOptimizationToolUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.KSPTrajectoryOptimizationToolUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KSPTrajectoryOptimizationToolUIFigure)
        end
    end
end