classdef optionsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        optionsGUI                 matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout3                matlab.ui.container.GridLayout
        okButton                   matlab.ui.control.Button
        GridLayout2                matlab.ui.container.GridLayout
        GridLayout5                matlab.ui.container.GridLayout
        uipanel3                   matlab.ui.container.Panel
        GridLayout7                matlab.ui.container.GridLayout
        optimizerQuantityBtnGroup  matlab.ui.container.ButtonGroup
        departPArrivalDVRadioBtn   matlab.ui.control.RadioButton
        arrivalDVRadioBtn          matlab.ui.control.RadioButton
        departDVRadioBtn           matlab.ui.control.RadioButton
        text8                      matlab.ui.control.Label
        uipanel2                   matlab.ui.container.Panel
        GridLayout6                matlab.ui.container.GridLayout
        departPlotNumOptIters      matlab.ui.control.EditField
        text4                      matlab.ui.control.Label
        uipanel1                   matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        porkchopMaxDeltaV          matlab.ui.control.EditField
        text6                      matlab.ui.control.Label
        porkchopNumSynPeriods      matlab.ui.control.EditField
        text3                      matlab.ui.control.Label
        porkchopPtsAxesText        matlab.ui.control.EditField
        label1                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput
    end

    methods (Access = private)
        function saveAndClose(app, handles)
            try
%                 varargout{1} = handles.output;
            
                mainGUIFigure = getappdata(handles.optionsGUI, 'mainGUIFigure');
                userData = get(mainGUIFigure, 'UserData');
                options = userData{1,9};
            
                options.porkchopptsaxes = str2double(get(handles.porkchopPtsAxesText,'String'));
                options.plotmaxdeltav = str2double(get(handles.porkchopMaxDeltaV,'String'));
                options.porkchopnumsynperiods = str2double(get(handles.porkchopNumSynPeriods,'String'));
                options.departplotnumoptiters = str2double(get(handles.departPlotNumOptIters,'String'));
                options.quant2opt = get(get(handles.optimizerQuantityBtnGroup,'SelectedObject'),'tag');
                userData{1,9} = options;
                set(mainGUIFigure, 'UserData',userData);
                
                updateAppOptions(mainGUIFigure, 'ksptot', 'plotmaxdeltav', options.plotmaxdeltav); pause(0.1);
                updateAppOptions(mainGUIFigure, 'ksptot', 'porkchopptsaxes', options.porkchopptsaxes); pause(0.1);
                updateAppOptions(mainGUIFigure, 'ksptot', 'porkchopnumsynperiods', options.porkchopnumsynperiods); pause(0.1);
                updateAppOptions(mainGUIFigure, 'ksptot', 'departplotnumoptiters', options.departplotnumoptiters); pause(0.1);
                updateAppOptions(mainGUIFigure, 'ksptot', 'quant2opt', options.quant2opt);
                
                app.output.output{1} = userData;
                app.output.output{2} = handles.output;
                
                close(handles.optionsGUI);
            catch ME %#ok<NASGU> 
%                 varargout{1} = -1;
%                 userData = {};
                
                app.output.output{1} = {};
                app.output.output{2} = -1;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function optionsGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to optionsGUI (see VARARGIN)

            % Choose default command line output for optionsGUI
            handles.output = hObject;

            % Update handles structure
            guidata(hObject, handles);
            
            centerUIFigure(app.optionsGUI);
            applySelectedThemeToApp(app);

            %Possible fix for people with display issues.
%             checkForCharUnitsInGUI(hObject);

            mainGUIFigure = varargin{1};
            userData = get(mainGUIFigure, 'UserData');
            set(hObject, 'UserData', userData);
            setappdata(hObject, 'mainGUIFigure', mainGUIFigure);
            
            app.output = varargin{2};

            options = userData{1,9};
            set(handles.porkchopPtsAxesText,'String', num2str(options.porkchopptsaxes));
            set(handles.porkchopMaxDeltaV,'String', num2str(options.plotmaxdeltav));
            set(handles.porkchopNumSynPeriods,'String', num2str(options.porkchopnumsynperiods));
            set(handles.departPlotNumOptIters,'String', num2str(options.departplotnumoptiters));
            set(handles.optimizerQuantityBtnGroup,'SelectedObject', findobj('tag',options.quant2opt));

            % UIWAIT makes optionsGUI wait for user response (see UIRESUME)
            uiwait(handles.optionsGUI);
        end

        % Value changed function: departPlotNumOptIters
        function departPlotNumOptIters_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to departPlotNumOptIters (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of departPlotNumOptIters as text
            %        str2double(get(hObject,'String')) returns contents of departPlotNumOptIters as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Button pushed function: okButton
        function okButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to okButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = {};

            porkchopPtsAxes = str2double(get(handles.porkchopPtsAxesText,'String'));
            enteredStr = get(handles.porkchopPtsAxesText,'String');
            numberName = 'Number of points per porkchop plot axis';
            lb = 10;
            ub = 10000;
            isInt = true;
            errMsg = validateNumber(porkchopPtsAxes, numberName, lb, ub, isInt, errMsg, enteredStr);

            plotMaxDeltaV = str2double(get(handles.porkchopMaxDeltaV,'String'));
            enteredStr = get(handles.porkchopMaxDeltaV,'String');
            numberName = 'Porkchop plot maximum delta-v';
            lb = 0.1;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(plotMaxDeltaV, numberName, lb, ub, isInt, errMsg, enteredStr);

            porkchopNumSynPeriods = str2double(get(handles.porkchopNumSynPeriods,'String'));
            enteredStr = get(handles.porkchopNumSynPeriods,'String');
            numberName = 'Number of porkchop plot synodic periods';
            lb = 0.1;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(porkchopNumSynPeriods, numberName, lb, ub, isInt, errMsg, enteredStr);

            departPlotNumOptIters = str2double(get(handles.departPlotNumOptIters,'String'));
            enteredStr = get(handles.departPlotNumOptIters,'String');
            numberName = 'Number of departure computation search iterations';
            lb = 10;
            ub = 1000;
            isInt = true;
            errMsg = validateNumber(departPlotNumOptIters, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
%                 uiresume(handles.optionsGUI);
                app.saveAndClose(handles);
            else
%                 msgbox(errMsg,'Errors were found in application options inputs','error');
                uialert(app.optionsGUI, errMsg, 'Input Errors', "Icon","error");
            end
        end

        % Value changed function: porkchopMaxDeltaV
        function porkchopMaxDeltaV_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to porkchopMaxDeltaV (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of porkchopMaxDeltaV as text
            %        str2double(get(hObject,'String')) returns contents of porkchopMaxDeltaV as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: porkchopNumSynPeriods
        function porkchopNumSynPeriods_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to porkchopNumSynPeriods (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of porkchopNumSynPeriods as text
            %        str2double(get(hObject,'String')) returns contents of porkchopNumSynPeriods as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: porkchopPtsAxesText
        function porkchopPtsAxesText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to porkchopPtsAxesText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of porkchopPtsAxesText as text
            %        str2double(get(hObject,'String')) returns contents of porkchopPtsAxesText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.optionsGUI = uifigure('Visible', 'off');
            colormap(app.optionsGUI, 'parula');
            app.optionsGUI.Name = 'Options';
            app.optionsGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.optionsGUI.Tag = 'optionsGUI';
            app.GridLayout = uigridlayout(app.optionsGUI);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.uipanel1 = uipanel(app.GridLayout2);
            app.uipanel1.Title = 'Porkchop Plot Options';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout4 = uigridlayout(app.uipanel1);
            app.label1 = uilabel(app.GridLayout4);
            app.label1.Tag = 'label1';
            app.label1.Text = 'Number of Points Per Axis';
            app.porkchopPtsAxesText = uieditfield(app.GridLayout4, 'text');
            app.porkchopPtsAxesText.ValueChangedFcn = createCallbackFcn(app, @porkchopPtsAxesText_Callback, true);
            app.porkchopPtsAxesText.Tag = 'porkchopPtsAxesText';
            app.porkchopPtsAxesText.Value = '100';
            app.text3 = uilabel(app.GridLayout4);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Number of Synodic Periods to Plot';
            app.porkchopNumSynPeriods = uieditfield(app.GridLayout4, 'text');
            app.porkchopNumSynPeriods.ValueChangedFcn = createCallbackFcn(app, @porkchopNumSynPeriods_Callback, true);
            app.porkchopNumSynPeriods.Tag = 'porkchopNumSynPeriods';
            app.porkchopNumSynPeriods.Value = '1';
            app.text6 = uilabel(app.GridLayout4);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Maximum Delta-V to Plot';
            app.porkchopMaxDeltaV = uieditfield(app.GridLayout4, 'text');
            app.porkchopMaxDeltaV.ValueChangedFcn = createCallbackFcn(app, @porkchopMaxDeltaV_Callback, true);
            app.porkchopMaxDeltaV.Tag = 'porkchopMaxDeltaV';
            app.porkchopMaxDeltaV.Value = '20';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.uipanel2 = uipanel(app.GridLayout5);
            app.uipanel2.Title = 'Compute Departure Options';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout6 = uigridlayout(app.uipanel2);
            app.text4 = uilabel(app.GridLayout6);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Number of Optimization Iterations';
            app.departPlotNumOptIters = uieditfield(app.GridLayout6, 'text');
            app.departPlotNumOptIters.ValueChangedFcn = createCallbackFcn(app, @departPlotNumOptIters_Callback, true);
            app.departPlotNumOptIters.Tag = 'departPlotNumOptIters';
            app.departPlotNumOptIters.Value = '50';
            app.uipanel3 = uipanel(app.GridLayout5);
            app.uipanel3.Title = 'Optimizer Options';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout7 = uigridlayout(app.uipanel3);
            app.text8 = uilabel(app.GridLayout7);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Quantity to Optimize';
            app.optimizerQuantityBtnGroup = uibuttongroup(app.GridLayout7);
            app.optimizerQuantityBtnGroup.Tag = 'optimizerQuantityBtnGroup';
            app.departDVRadioBtn = uiradiobutton(app.optimizerQuantityBtnGroup);
            app.departDVRadioBtn.Tag = 'departDVRadioBtn';
            app.departDVRadioBtn.Text = 'Departure Delta-V Only';
            app.arrivalDVRadioBtn = uiradiobutton(app.optimizerQuantityBtnGroup);
            app.arrivalDVRadioBtn.Tag = 'arrivalDVRadioBtn';
            app.arrivalDVRadioBtn.Text = 'Arrival Delta-V Only';
            app.departPArrivalDVRadioBtn = uiradiobutton(app.optimizerQuantityBtnGroup);
            app.departPArrivalDVRadioBtn.Tag = 'departPArrivalDVRadioBtn';
            app.departPArrivalDVRadioBtn.Text = 'Departure+Arrival Delta-V';
            app.departPArrivalDVRadioBtn.Value = true;
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.okButton = uibutton(app.GridLayout3, 'push');
            app.okButton.ButtonPushedFcn = createCallbackFcn(app, @okButton_Callback, true);
            app.okButton.Tag = 'okButton';
            app.okButton.Text = 'Save and Close';
            % Show the figure after all components are created
            app.optionsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optionsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.optionsGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)optionsGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.optionsGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.optionsGUI)
        end
    end
end