classdef splashScreenGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        splashScreenGUI        matlab.ui.Figure
        text2                  matlab.ui.control.Label
        versionCopyrightLabel  matlab.ui.control.Label
        logoAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function splashScreenGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to splashScreenGUI (see VARARGIN)

            % Choose default command line output for splashScreenGUI
            handles.output = hObject;
            centerUIFigure(app.splashScreenGUI);
            
            % Update handles structure
            guidata(hObject, handles);

            if isunix
                rgb = imread('images/logo500px.jpg');
            else
                rgb = imread('images\logo500px.jpg');
            end
            image(rgb,'Parent',handles.logoAxes);
            axis off;
            axis image;

            str = sprintf('Version: %s (R%s)\n(C) 2023 Arrowstar',getKSPTOTVersionNumStr(),version('-release'));
            set(handles.versionCopyrightLabel,'String',str);

            set(handles.splashScreenGUI,'Name',['KSP Trajectory Optimization Tool ',getKSPTOTVersionNumStr()]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.splashScreenGUI = uifigure('Visible', 'off');
            colormap(app.splashScreenGUI, 'parula');
            app.splashScreenGUI.Name = 'KSP Trajectory Optimization Tool v1.1.0';
            app.splashScreenGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.splashScreenGUI.Tag = 'splashScreenGUI';
            app.logoAxes = uiaxes(app.splashScreenGUI);
            app.logoAxes.Tag = 'logoAxes';
            app.versionCopyrightLabel = uilabel(app.splashScreenGUI);
            app.versionCopyrightLabel.Tag = 'versionCopyrightLabel';
            app.versionCopyrightLabel.Text = {'Version: v1.1.0'; '(C) 2023 Arrowstar'};
            app.text2 = uilabel(app.splashScreenGUI);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Loading...';
            % Show the figure after all components are created
            app.splashScreenGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = splashScreenGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.splashScreenGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)splashScreenGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.splashScreenGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.splashScreenGUI)
        end
    end
end