classdef uploadManeuverToKSP_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        uploadManeuver2KSP    matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        GridLayout2           matlab.ui.container.GridLayout
        GridLayout4           matlab.ui.container.GridLayout
        uploadManButton       matlab.ui.control.Button
        testConnectionButton  matlab.ui.control.Button
        timeTypeBtnGroup      matlab.ui.container.ButtonGroup
        timePastPeriRadioBtn  matlab.ui.control.RadioButton
        utTimeRadioBtn        matlab.ui.control.RadioButton
        manInfo               matlab.ui.container.Panel
        GridLayout3           matlab.ui.container.GridLayout
        text6                 matlab.ui.control.Label
        text4                 matlab.ui.control.Label
        text9                 matlab.ui.control.Label
        text8                 matlab.ui.control.Label
        radialDVText          matlab.ui.control.EditField
        normalDVText          matlab.ui.control.EditField
        progradeDVText        matlab.ui.control.EditField
        utText                matlab.ui.control.EditField
        text7                 matlab.ui.control.Label
        text5                 matlab.ui.control.Label
        text3                 matlab.ui.control.Label
        timeTypeLabel         matlab.ui.control.Label
        statusText            matlab.ui.control.TextArea
        enterUTAsDateTime     matlab.ui.container.ContextMenu
        enterUTAsDateTime_1   matlab.ui.container.Menu
    end


    methods (Access = private)
        function checkConnectionWKSP(app, hStatusText, first)
            % if(first == true)
                writeToOutput(hStatusText,'*** Checking Connection Status ***','append');
            % else
            %     writeToOutput(hStatusText,'*** Checking Connection Status ***','append');
            % end

            dataType = 'ConnectionCheck';
            data = [1 2 3 4 5];

            try
                writeDataToKSPTOTConnect(dataType, data);
                failed = false;
            catch ME
                msg = ['Connection to KSPTOT Connect failed: ', ME.message];
                failed = true;
            end

            if(failed == false)
                msg = 'Connection to KSPTOT Connect succeeded!';
            end

            writeToOutput(hStatusText,msg,'append');
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function uploadManeuverToKSP_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to uploadManeuverToKSP (see VARARGIN)

            % Choose default command line output for uploadManeuverToKSP
            handles.output = hObject;

            % Update handles structure
            guidata(hObject, handles);

            centerUIFigure(app.uploadManeuver2KSP);
            applySelectedThemeToApp(app);

            %Possible fix for people with display issues.
            %                 checkForCharUnitsInGUI(hObject);

            if(nargin > 1)
                data = varargin{1};
                if(numel(data) == 5)
                    if(~isempty(data))
                        set(handles.utText, 'String', fullAccNum2Str(data(2)));
                        set(handles.progradeDVText, 'String', fullAccNum2Str(data(3)));
                        set(handles.normalDVText, 'String', fullAccNum2Str(data(4)));
                        set(handles.radialDVText, 'String', fullAccNum2Str(data(5)));

                        if(data(1) == 0)
                            set(handles.timeTypeBtnGroup, 'SelectedObject', handles.utTimeRadioBtn);
                            eventdata.NewValue = handles.utTimeRadioBtn;
                        elseif(data(1) == 1)
                            set(handles.timeTypeBtnGroup, 'SelectedObject', handles.timePastPeriRadioBtn);
                            eventdata.NewValue = handles.timePastPeriRadioBtn;
                        else
                            set(handles.timeTypeBtnGroup, 'SelectedObject', handles.utTimeRadioBtn);
                            eventdata.NewValue = handles.utTimeRadioBtn;
                        end

                        evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                        timeTypeBtnGroup_SelectionChangeFcn(app, evt);
                    else
                        eventdata.NewValue = handles.utTimeRadioBtn;
                        evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                        timeTypeBtnGroup_SelectionChangeFcn(app, evt);
                    end
                end
            else
                eventdata.NewValue = handles.utTimeRadioBtn;
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                timeTypeBtnGroup_SelectionChangeFcn(app, evt);
            end

            checkConnectionWKSP(app, handles.statusText, true);

            % UIWAIT makes uploadManeuverToKSP wait for user response (see UIRESUME)
            % uiwait(handles.uploadManeuver2KSP);
        end

        % Menu selected function: enterUTAsDateTime_1
        function enterUTAsDateTime_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to enterUTAsDateTime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            secUT = enterUTAsDateTimeGUI(str2double(get(gco, 'String')));
            if(secUT >= 0)
                set(gco, 'String', num2str(secUT));
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                utText_Callback(app, evt);
            end
        end

        % Value changed function: normalDVText
        function normalDVText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to normalDVText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of normalDVText as text
            %        str2double(get(hObject,'String')) returns contents of normalDVText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: progradeDVText
        function progradeDVText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to progradeDVText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of progradeDVText as text
            %        str2double(get(hObject,'String')) returns contents of progradeDVText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: radialDVText
        function radialDVText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to radialDVText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of radialDVText as text
            %        str2double(get(hObject,'String')) returns contents of radialDVText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end

        % Value changed function: statusText
        function statusText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to statusText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of statusText as text
            %        str2double(get(hObject,'String')) returns contents of statusText as a double
        end

        % Button pushed function: testConnectionButton
        function testConnectionButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to testConnectionButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            checkConnectionWKSP(app, handles.statusText, false);
        end

        % Selection changed function: timeTypeBtnGroup
        function timeTypeBtnGroup_SelectionChangeFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to the selected object in timeTypeBtnGroup
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            % handles    structure with handles and user data (see GUIDATA)
%             hSelected = eventdata.NewValue;
%             tagSelected = get(hSelected, 'tag');

            selected = app.timeTypeBtnGroup.SelectedObject;

            if(selected == app.utTimeRadioBtn)
                set(handles.timeTypeLabel,'String','Univ. Time (UT)');
                set(handles.timeTypeLabel,'TooltipString','Universal game time');
                set(handles.utText,'TooltipString','Universal game time');
            elseif(selected == app.timePastPeriRadioBtn)
                set(handles.timeTypeLabel,'String','Time Past Peri.');
                set(handles.timeTypeLabel,'TooltipString','Time past upcoming periapse');
                set(handles.utText,'TooltipString','Time past upcoming periapse');
            end
        end

        % Button pushed function: uploadManButton
        function uploadManButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to uploadManButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            writeToOutput(handles.statusText,'*** Generating Maneuver Node... ***','append');

            ut = str2double(get(handles.utText, 'String'));
            pDV = str2double(get(handles.progradeDVText, 'String'));
            nDV = str2double(get(handles.normalDVText, 'String'));
            rDV = str2double(get(handles.radialDVText, 'String'));

            hSelected = get(handles.timeTypeBtnGroup, 'SelectedObject');
            hSelTag = get(hSelected,'Tag');

            if(strcmpi(hSelTag, 'utTimeRadioBtn'))
                dataType = 'NodeData';
            elseif(strcmpi(hSelTag, 'timePastPeriRadioBtn'))
                dataType = 'NodeDataWRTPeri';
            end

            data = [ut pDV nDV rDV];

            try
                tcpipClient = createTcpIpClient(8282, 'Client');
                writeDataToKSPTOTConnect(dataType, data, 'd', tcpipClient);
                failed = false;
            catch ME
                msg = ['Connection to KSPTOT Connect failed: ', ME.message];
                failed = true;
            end

            if(failed == false)
                msg = 'Maneuver Node created!';
            end

            writeToOutput(handles.statusText,msg,'append');
        end

        % Value changed function: utText
        function utText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.utText;

            % hObject    handle to utText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of utText as text
            %        str2double(get(hObject,'String')) returns contents of utText as a double
            newInput = attemptStrEval(get(hObject,'String'));
            set(hObject, 'String', newInput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.uploadManeuver2KSP = uifigure('Visible', 'off');
            colormap(app.uploadManeuver2KSP, 'parula');
            app.uploadManeuver2KSP.Name = 'Upload Maneuver to KSP';
            app.uploadManeuver2KSP.Icon = 'logoSquare_48px_transparentBg.png';
            app.uploadManeuver2KSP.Tag = 'uploadManeuver2KSP';
            app.GridLayout = uigridlayout(app.uploadManeuver2KSP);
            app.statusText = uitextarea(app.GridLayout);
            app.statusText.ValueChangedFcn = createCallbackFcn(app, @statusText_Callback, true);
            app.statusText.Tag = 'statusText';
            app.statusText.Value = {'*** KSPTOT Connect Maneuver Upload ***'; '---------------------------------------------------------'};
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.manInfo = uipanel(app.GridLayout2);
            app.manInfo.Title = 'Maneuver Information';
            app.manInfo.Tag = 'manInfo';
            app.GridLayout3 = uigridlayout(app.manInfo);
            app.timeTypeLabel = uilabel(app.GridLayout3);
            app.timeTypeLabel.Tag = 'timeTypeLabel';
            app.timeTypeLabel.Tooltip = 'Universal game time';
            app.timeTypeLabel.Text = 'Univ. Time (UT)';
            app.text3 = uilabel(app.GridLayout3);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Prograde DV';
            app.text5 = uilabel(app.GridLayout3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Normal DV';
            app.text7 = uilabel(app.GridLayout3);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Radial DV';
            app.utText = uieditfield(app.GridLayout3, 'text');
            app.utText.ValueChangedFcn = createCallbackFcn(app, @utText_Callback, true);
            app.utText.Tag = 'utText';
            app.utText.Tooltip = 'Universal game time';
            app.utText.Value = '0.0';
            app.progradeDVText = uieditfield(app.GridLayout3, 'text');
            app.progradeDVText.ValueChangedFcn = createCallbackFcn(app, @progradeDVText_Callback, true);
            app.progradeDVText.Tag = 'progradeDVText';
            app.progradeDVText.Value = '0.0';
            app.normalDVText = uieditfield(app.GridLayout3, 'text');
            app.normalDVText.ValueChangedFcn = createCallbackFcn(app, @normalDVText_Callback, true);
            app.normalDVText.Tag = 'normalDVText';
            app.normalDVText.Value = '0.0';
            app.radialDVText = uieditfield(app.GridLayout3, 'text');
            app.radialDVText.ValueChangedFcn = createCallbackFcn(app, @radialDVText_Callback, true);
            app.radialDVText.Tag = 'radialDVText';
            app.radialDVText.Value = '0.0';
            app.text8 = uilabel(app.GridLayout3);
            app.text8.Tag = 'text8';
            app.text8.Text = 'sec';
            app.text9 = uilabel(app.GridLayout3);
            app.text9.Tag = 'text9';
            app.text9.Text = 'm/s';
            app.text4 = uilabel(app.GridLayout3);
            app.text4.Tag = 'text4';
            app.text4.Text = 'm/s';
            app.text6 = uilabel(app.GridLayout3);
            app.text6.Tag = 'text6';
            app.text6.Text = 'm/s';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.timeTypeBtnGroup = uibuttongroup(app.GridLayout4);
            app.timeTypeBtnGroup.SelectionChangedFcn = createCallbackFcn(app, @timeTypeBtnGroup_SelectionChangeFcn, true);
            app.timeTypeBtnGroup.Title = 'Time Type';
            app.timeTypeBtnGroup.Tag = 'timeTypeBtnGroup';
            app.utTimeRadioBtn = uiradiobutton(app.timeTypeBtnGroup);
            app.utTimeRadioBtn.Tag = 'utTimeRadioBtn';
            app.utTimeRadioBtn.Text = 'Univ. Time';
            app.utTimeRadioBtn.Value = true;
            app.timePastPeriRadioBtn = uiradiobutton(app.timeTypeBtnGroup);
            app.timePastPeriRadioBtn.Tag = 'timePastPeriRadioBtn';
            app.timePastPeriRadioBtn.Text = 'Time Past Peri.';
            app.testConnectionButton = uibutton(app.GridLayout4, 'push');
            app.testConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @testConnectionButton_Callback, true);
            app.testConnectionButton.Tag = 'testConnectionButton';
            app.testConnectionButton.Text = 'Test Connection';
            app.uploadManButton = uibutton(app.GridLayout4, 'push');
            app.uploadManButton.ButtonPushedFcn = createCallbackFcn(app, @uploadManButton_Callback, true);
            app.uploadManButton.Tag = 'uploadManButton';
            app.uploadManButton.Text = 'Upload Maneuver!';
            app.enterUTAsDateTime = uicontextmenu(app.uploadManeuver2KSP);
            app.enterUTAsDateTime.Tag = 'enterUTAsDateTime';
            app.enterUTAsDateTime_1 = uimenu(app.enterUTAsDateTime);
            app.enterUTAsDateTime_1.MenuSelectedFcn = createCallbackFcn(app, @enterUTAsDateTime_Callback, true);
            app.enterUTAsDateTime_1.Text = 'Enter UT As Date/Time';
            app.enterUTAsDateTime_1.Tag = 'enterUTAsDateTime_1';
            % Assign app.enterUTAsDateTime
            app.utText.ContextMenu = app.enterUTAsDateTime;
            % Show the figure after all components are created
            app.uploadManeuver2KSP.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uploadManeuverToKSP_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.uploadManeuver2KSP)

                % Execute the startup function
                runStartupFcn(app, @(app)uploadManeuverToKSP_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.uploadManeuver2KSP)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.uploadManeuver2KSP)
        end
    end
end