classdef vs_mainGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        vs_mainGUI           matlab.ui.Figure
        fileMenu             matlab.ui.container.Menu
        loadVstCaseMenu      matlab.ui.container.Menu
        saveVstCaseMenu      matlab.ui.container.Menu
        GridLayout           matlab.ui.container.GridLayout
        GridLayout3          matlab.ui.container.GridLayout
        GridLayout5          matlab.ui.container.GridLayout
        stagesPanel          matlab.ui.container.Panel
        GridLayout12         matlab.ui.container.GridLayout
        GridLayout19         matlab.ui.container.GridLayout
        GridLayout20         matlab.ui.container.GridLayout
        GridLayout21         matlab.ui.container.GridLayout
        moveStageDownButton  matlab.ui.control.Button
        moveStageUpButton    matlab.ui.control.Button
        stagesListbox        matlab.ui.control.ListBox
        addStageButton       matlab.ui.control.Button
        deleteStageButton    matlab.ui.control.Button
        uipanel4             matlab.ui.container.Panel
        GridLayout13         matlab.ui.container.GridLayout
        uipanel7             matlab.ui.container.Panel
        GridLayout14         matlab.ui.container.GridLayout
        GridLayout22         matlab.ui.container.GridLayout
        Image2               matlab.ui.control.Image
        bodyCombo            matlab.ui.control.DropDown
        GridLayout18         matlab.ui.container.GridLayout
        text19               matlab.ui.control.Label
        payloadMassText      matlab.ui.control.EditField
        text18               matlab.ui.control.Label
        GridLayout17         matlab.ui.container.GridLayout
        text16               matlab.ui.control.Label
        desiredT2WText       matlab.ui.control.EditField
        text15               matlab.ui.control.Label
        GridLayout16         matlab.ui.container.GridLayout
        text14               matlab.ui.control.Label
        dryMassFractionText  matlab.ui.control.EditField
        text13               matlab.ui.control.Label
        GridLayout15         matlab.ui.container.GridLayout
        text12               matlab.ui.control.Label
        stageIspText         matlab.ui.control.EditField
        stageIspLabel        matlab.ui.control.Label
        isPayloadCheckbox    matlab.ui.control.CheckBox
        text17               matlab.ui.control.Label
        stageNameText        matlab.ui.control.EditField
        uipanel1             matlab.ui.container.Panel
        GridLayout6          matlab.ui.container.GridLayout
        GridLayout9          matlab.ui.container.GridLayout
        GridLayout10         matlab.ui.container.GridLayout
        GridLayout11         matlab.ui.container.GridLayout
        movePhaseDownButton  matlab.ui.control.Button
        movePhaseUpButton    matlab.ui.control.Button
        phasesListbox        matlab.ui.control.ListBox
        addPhaseButton       matlab.ui.control.Button
        deletePhaseButton    matlab.ui.control.Button
        uipanel3             matlab.ui.container.Panel
        GridLayout7          matlab.ui.container.GridLayout
        uipanel5             matlab.ui.container.Panel
        GridLayout8          matlab.ui.container.GridLayout
        Image3               matlab.ui.control.Image
        text8                matlab.ui.control.Label
        phaseDvText          matlab.ui.control.EditField
        text7                matlab.ui.control.Label
        phaseNameText        matlab.ui.control.EditField
        runSizingButton      matlab.ui.control.Button
        uipanel8             matlab.ui.container.Panel
        GridLayout4          matlab.ui.container.GridLayout
        outputText           matlab.ui.control.TextArea
        GridLayout2          matlab.ui.container.GridLayout
        text2                matlab.ui.control.Label
        Image                matlab.ui.control.Image
    end

    
    properties (Access = private)
        celBodyData % Description
    end

    methods (Access = private)
        function selPhase = getSelectedPhase(~, handles)
            vsProb = getappdata(handles.vs_mainGUI,'vsProb');

            selInd = get(handles.phasesListbox,'Value');
            selPhase = vsProb.getPhaseForInd(selInd);
        end

        function selStage = getSelectedStage(app, handles)
            selPhase = getSelectedPhase(app, handles);

            selInd = get(handles.stagesListbox,'Value');
            selStage = selPhase.getStageForInd(selInd);
        end

        function populateGUI(app, vsProb, handles)
%             set(handles.movePhaseUpButton,'String','<html>&uarr;');
%             set(handles.movePhaseDownButton,'String','<html>&darr;');
%             set(handles.moveStageUpButton,'String','<html>&uarr;');
%             set(handles.moveStageDownButton,'String','<html>&darr;');

%             jScrollPane = findjobj(handles.outputText);

            % Modify the scroll-pane's scrollbar policies
%             set(jScrollPane,'VerticalScrollBarPolicy',20);  % or: jScrollPane.VERTICAL_SCROLLBAR_AS_NEEDED

%             jViewPort = jScrollPane.getViewport;
%             jEditbox = jViewPort.getComponent(0);
%             jEditbox.setWrapping(false);  % do *NOT* use set(...)!!!
%             jEditbox.setEditable(false);
%             set(jScrollPane,'HorizontalScrollBarPolicy',30);

            handles.phasesListbox.String = vsProb.getPhasesListboxStr();
            handles.phasesListbox.Value = 1;

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            phasesListbox_Callback(app, evt);
        end

        function stageIspLabel_CreateFcn(~, hObject, ~, ~)
            % hObject    handle to stageIspLabel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function updatePhase(app, handles)
            vsProb = getappdata(handles.vs_mainGUI,'vsProb');
            selPhase = getSelectedPhase(app, handles);

            selPhase.name = handles.phaseNameText.String;
            handles.stagesPanel.Title = sprintf('Stages (Phase = %s)',selPhase.name);
            handles.phasesListbox.String = vsProb.getPhasesListboxStr();

            selPhase.phaseDv = str2double(handles.phaseDvText.String);

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            phasesListbox_Callback(app, evt);
        end

        function updateStage(app, handles)
            % celBodyData = getappdata(handles.vs_mainGUI,'celBodyData');

            selPhase = getSelectedPhase(app, handles);
            selStage = getSelectedStage(app, handles);

            selStage.name = handles.stageNameText.String;
            selStage.isp = str2double(handles.stageIspText.String);
            selStage.dryMassFrac = str2double(handles.dryMassFractionText.String);
            selStage.desiredT2W = str2double(handles.desiredT2WText.String);

            selBodyInd = handles.bodyCombo.Value;
            selBodyStr = lower(strtrim(handles.bodyCombo.String{selBodyInd}));
            selStage.bodyInfo = app.celBodyData.(selBodyStr);

            selStage.isPayload = logical(handles.isPayloadCheckbox.Value);
            if(selStage.isPayload)
                handles.payloadMassText.Enable = 'on';
            else
                handles.payloadMassText.Enable = 'off';
            end

            selStage.dryMass = str2double(handles.payloadMassText.String)*1000; %mT -> kg

            handles.stagesListbox.String = selPhase.getStagesListboxStr();
            if(handles.stagesListbox.Value > length(selPhase.stages))
                handles.stagesListbox.Value = length(selPhase.stages);
            end

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            stagesListbox_Callback(app, evt);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function vs_mainGUI_OpeningFcn(app, celBodyData)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to vs_mainGUI (see VARARGIN)

            % Choose default command line output for vs_mainGUI
            handles.output = hObject;
            centerUIFigure(app.vs_mainGUI);
            applySelectedThemeToApp(app);

            app.celBodyData = celBodyData;

            % celBodyData = varargin{1};
            % setappdata(hObject,'celBodyData',celBodyData);

            vsProb = VS_Problem.getDefaultVsProblem(celBodyData);
            setappdata(hObject,'vsProb',vsProb);

            populateBodiesCombo(celBodyData, handles.bodyCombo);

            populateGUI(app, vsProb, handles);

            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: addPhaseButton
        function addPhaseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addPhaseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % celBodyData = getappdata(handles.vs_mainGUI,'celBodyData');
            vsProb = getappdata(handles.vs_mainGUI,'vsProb');

            bodyNames = fields(app.celBodyData);
            bodyInfo = app.celBodyData.(bodyNames{1});

            newPhase = VS_Phase(vsProb);
            vsProb.addPhase(newPhase);

            newStage = VS_Stage(newPhase);
            newStage.bodyInfo = bodyInfo;
            newPhase.addStage(newStage);

            handles.phasesListbox.String = vsProb.getPhasesListboxStr();
            handles.phasesListbox.Value = length(vsProb.phases);
            
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            phasesListbox_Callback(app, evt);

            handles.deletePhaseButton.Enable = 'on';
        end

        % Button pushed function: addStageButton
        function addStageButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addStageButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % celBodyData = getappdata(handles.vs_mainGUI,'celBodyData');
            selPhase = getSelectedPhase(app, handles);

            bodyNames = fields(app.celBodyData);
            bodyInfo = app.celBodyData.(bodyNames{1});

            newStage = VS_Stage(selPhase);
            newStage.bodyInfo = bodyInfo;
            selPhase.addStage(newStage);

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            phasesListbox_Callback(app, evt);

            handles.deleteStageButton.Enable = 'on';
        end

        % Value changed function: bodyCombo
        function bodyCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to bodyCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns bodyCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from bodyCombo
            updateStage(app, handles);
        end

        % Button pushed function: deletePhaseButton
        function deletePhaseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to deletePhaseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            vsProb = getappdata(handles.vs_mainGUI,'vsProb');

            selPhase = getSelectedPhase(app, handles);
            vsProb.removePhase(selPhase);

            if(handles.phasesListbox.Value > length(vsProb.phases))
                handles.phasesListbox.Value = length(vsProb.phases);
            end

            handles.phasesListbox.String = vsProb.getPhasesListboxStr();
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            phasesListbox_Callback(app, evt);

            if(length(vsProb.phases) <= 1)
                handles.deletePhaseButton.Enable = 'off';
            else
                handles.deletePhaseButton.Enable = 'on';
            end
        end

        % Button pushed function: deleteStageButton
        function deleteStageButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to deleteStageButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            selPhase = getSelectedPhase(app, handles);
            selStage = getSelectedStage(app, handles);

            selPhase.removeStage(selStage);
            phasesListbox_Callback(app, event);

            if(length(selPhase.stages) <= 1)
                handles.deleteStageButton.Enable = 'off';
            else
                handles.deleteStageButton.Enable = 'on';
            end
        end

        % Value changed function: desiredT2WText
        function desiredT2WText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to desiredT2WText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of desiredT2WText as text
            %        str2double(get(hObject,'String')) returns contents of desiredT2WText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            errMsg = {};

            rawDouble = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Stage Desired Thrust to Weight Ratio';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(rawDouble, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(not(isempty(errMsg)))
                selStage = getSelectedStage(app, handles);
                hObject.String = fullAccNum2Str(selStage.desiredT2W);

                msgbox(errMsg,'Vehicle Sizing Tool Input Error','error');
            else
                updateStage(app, handles);
            end
        end

        % Value changed function: dryMassFractionText
        function dryMassFractionText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to dryMassFractionText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of dryMassFractionText as text
            %        str2double(get(hObject,'String')) returns contents of dryMassFractionText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            errMsg = {};

            rawDouble = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Stage Dry Mass Fraction';
            lb = 0;
            ub = 1;
            isInt = false;
            errMsg = validateNumber(rawDouble, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(not(isempty(errMsg)))
                selStage = getSelectedStage(app, handles);
                hObject.String = fullAccNum2Str(selStage.dryMassFrac);

                msgbox(errMsg,'Vehicle Sizing Tool Input Error','error');
            else
                updateStage(app, handles);
            end
        end

        % Value changed function: isPayloadCheckbox
        function isPayloadCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to isPayloadCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of isPayloadCheckbox
            updateStage(app, handles);
        end

        % Menu selected function: loadVstCaseMenu
        function loadVstCaseMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to loadVstCaseMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [FileName,PathName] = uigetfile({'*.mat','KSPTOT Vehicle Sizing Tool Case File (*.mat)'},...
                'Open Vehicle Sizing Tool Case',...
                'vstCase.mat');
            if(ischar(FileName) && ischar(PathName))
                filePath = [PathName,FileName];
            else
                return;
            end

            if(ischar(filePath))
                load(filePath); %#ok<LOAD>

                if(exist('vsProb','var'))
                    setappdata(handles.vs_mainGUI,'vsProb',vsProb);

                    populateGUI(app, vsProb, handles);
                end
            end
        end

        % Button pushed function: movePhaseDownButton
        function movePhaseDownButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to movePhaseDownButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            vsProb = getappdata(handles.vs_mainGUI,'vsProb');
            selPhase = getSelectedPhase(app, handles);

            phaseInd = vsProb.getIndOfPhase(selPhase);
            vsProb.movePhaseDown(selPhase);

            numPhases = length(vsProb.phases);
            if(phaseInd<numPhases)
                set(handles.phasesListbox,'Value',phaseInd+1);
            end

            set(handles.phasesListbox,'String',vsProb.getPhasesListboxStr());
        end

        % Button pushed function: movePhaseUpButton
        function movePhaseUpButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to movePhaseUpButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            vsProb = getappdata(handles.vs_mainGUI,'vsProb');
            selPhase = getSelectedPhase(app, handles);

            phaseInd = vsProb.getIndOfPhase(selPhase);
            vsProb.movePhaseUp(selPhase);

            if(phaseInd>1)
                set(handles.phasesListbox,'Value',phaseInd-1);
            end

            set(handles.phasesListbox,'String',vsProb.getPhasesListboxStr());
        end

        % Button pushed function: moveStageDownButton
        function moveStageDownButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to moveStageDownButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            selPhase = getSelectedPhase(app, handles);
            selStage = getSelectedStage(app, handles);

            stageInd = selPhase.getIndOfStage(selStage);
            selPhase.moveStageDown(selStage);

            numStages = length(selPhase.stages);
            if(stageInd<numStages)
                set(handles.stagesListbox,'Value',stageInd+1);
            end

            set(handles.stagesListbox,'String',selPhase.getStagesListboxStr());
        end

        % Button pushed function: moveStageUpButton
        function moveStageUpButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to moveStageUpButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            selPhase = getSelectedPhase(app, handles);
            selStage = getSelectedStage(app, handles);

            stageInd = selPhase.getIndOfStage(selStage);
            selPhase.moveStageUp(selStage);

            if(stageInd>1)
                set(handles.stagesListbox,'Value',stageInd-1);
            end

            set(handles.stagesListbox,'String',selPhase.getStagesListboxStr());
        end

        % Value changed function: payloadMassText
        function payloadMassText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to payloadMassText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of payloadMassText as text
            %        str2double(get(hObject,'String')) returns contents of payloadMassText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            errMsg = {};

            rawDouble = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Stage Payload Mass';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(rawDouble, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(not(isempty(errMsg)))
                selStage = getSelectedStage(app, handles);
                hObject.String = fullAccNum2Str(selStage.dryMass/1000);

                msgbox(errMsg,'Vehicle Sizing Tool Input Error','error');
            else
                updateStage(app, handles);
            end
        end

        % Value changed function: phaseDvText
        function phaseDvText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to phaseDvText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of phaseDvText as text
            %        str2double(get(hObject,'String')) returns contents of phaseDvText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            errMsg = {};

            rawDouble = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Phase Delta-V';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(rawDouble, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(not(isempty(errMsg)))
                selPhase = getSelectedPhase(app, handles);
                hObject.String = fullAccNum2Str(selPhase.phaseDv);

                msgbox(errMsg,'Vehicle Sizing Tool Input Error','error');
            else
                updatePhase(app, handles);
            end
        end

        % Value changed function: phaseNameText
        function phaseNameText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to phaseNameText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of phaseNameText as text
            %        str2double(get(hObject,'String')) returns contents of phaseNameText as a double
            updatePhase(app, handles);
        end

        % Value changed function: phasesListbox
        function phasesListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
%             hObject = handles.phasesListbox;
            
            % hObject    handle to phasesListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns phasesListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from phasesListbox
            selPhase = getSelectedPhase(app, handles);

            handles.phaseNameText.String = selPhase.name;
            handles.phaseDvText.String = fullAccNum2Str(selPhase.phaseDv);

            % handles.stagesListbox.String = selPhase.getStagesListboxStr();
            app.stagesListbox.Items = selPhase.getStagesListboxStr();
            if(handles.stagesListbox.Value > length(selPhase.stages))
                handles.stagesListbox.Value = length(selPhase.stages);
            end

            handles.stagesPanel.Title = sprintf('Stages (Phase = %s)',selPhase.name);

            stagesListbox_Callback(app, event);

            if(length(selPhase.stages) <= 1)
                handles.deleteStageButton.Enable = 'off';
            else
                handles.deleteStageButton.Enable = 'on';
            end
        end

        % Button pushed function: runSizingButton
        function runSizingButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to runSizingButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            vsProb = getappdata(handles.vs_mainGUI,'vsProb');
            initStage = vsProb.getProblemState();

            try
                % handles.runSizingButton.Enable = 'off';
                drawnow;

                % hWaitbar = waitbar(0,'Running Vehicle Sizing Analysis...');
                hWaitbar = uiprogressdlg(app.vs_mainGUI, "Title",'Running Vehicle Sizing Analysis...', 'Indeterminate','on', 'Icon','info');
                drawnow;
                
                [~,~,exitflag,output] = vsProb.sizeVehicle();

                if(isvalid(hWaitbar))
                    close(hWaitbar);
                end

                % handles.runSizingButton.Enable = 'on';
                handles.outputText.String = vsProb.getProblemOutputStr(exitflag,output);

%                 jhEdit = findjobj(handles.outputText);
%                 jEdit = jhEdit.getComponent(0).getComponent(0);
%                 jEdit.setCaretPosition(0);
            catch ME
                vsProb.updateStagesWithVars(initStage);

                handles.runSizingButton.Enable = 'on';

                if(isvalid(hWaitbar))
                    close(hWaitbar);
                end

                errorText = sprintf('An error was encountered during the vehicle sizing analysis.  Please verify your inputs (especially Isp values) and try again.  Error text:\n\n%s',ME.message);
                % errordlg(errorText,'Vehicle Sizing Tool','modal');
                uialert(app.vs_mainGUI, errorText, 'Vehicle Sizing Tool', 'Icon','error', 'Modal',true);
            end
        end

        % Menu selected function: saveVstCaseMenu
        function saveVstCaseMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveVstCaseMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [FileName,PathName] = uiputfile({'*.mat','KSPTOT Vehicle Sizing Tool Case File (*.mat)'},...
                'Open Vehicle Sizing Tool Case',...
                'vstCase.mat');
            if(ischar(FileName) && ischar(PathName))
                filePath = [PathName,FileName];
            else
                return;
            end

            if(ischar(FileName) && ischar(PathName))
                vsProb = getappdata(handles.vs_mainGUI,'vsProb');
                save(filePath,'vsProb');
            end
        end

        % Value changed function: stageIspText
        function stageIspText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to stageIspLabel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of stageIspLabel as text
            %        str2double(get(hObject,'String')) returns contents of stageIspLabel as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);

            errMsg = {};

            rawDouble = str2double(get(hObject,'String'));
            enteredStr = get(hObject,'String');
            numberName = 'Stage Engine Specific Impulse (Isp)';
            lb = 1;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(rawDouble, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(not(isempty(errMsg)))
                selStage = getSelectedStage(app, handles);
                hObject.String = fullAccNum2Str(selStage.isp);

                msgbox(errMsg,'Vehicle Sizing Tool Input Error','error');
            else
                updateStage(app, handles);
            end
        end

        % Value changed function: stageNameText
        function stageNameText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to stageNameText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of stageNameText as text
            %        str2double(get(hObject,'String')) returns contents of stageNameText as a double
            updateStage(app, handles);
        end

        % Value changed function: stagesListbox
        function stagesListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
%             hObject = handles.stagesListbox;
            
            % hObject    handle to stagesListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns stagesListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from stagesListbox
            selStage = getSelectedStage(app, handles);

            handles.stageNameText.String = selStage.name;
            handles.stageIspText.String = fullAccNum2Str(selStage.isp);
            handles.dryMassFractionText.String = fullAccNum2Str(selStage.dryMassFrac);
            handles.desiredT2WText.String = fullAccNum2Str(selStage.desiredT2W);

            value = findValueFromComboBox(selStage.bodyInfo.name, handles.bodyCombo);
            if(not(isempty(value)))
                handles.bodyCombo.Value = value;
            end

            handles.isPayloadCheckbox.Value = double(selStage.isPayload);
            if(selStage.isPayload)
                handles.payloadMassText.Enable = 'on';
            else
                handles.payloadMassText.Enable = 'off';
            end

            handles.payloadMassText.String = fullAccNum2Str(selStage.dryMass/1000);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.vs_mainGUI = uifigure('Visible', 'off');
            app.vs_mainGUI.Name = 'KSPTOT Vehicle Sizing Tool';
            app.vs_mainGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.vs_mainGUI.Tag = 'vs_mainGUI';
            app.fileMenu = uimenu(app.vs_mainGUI);
            app.fileMenu.Text = 'File';
            app.fileMenu.Tag = 'fileMenu';
            app.loadVstCaseMenu = uimenu(app.fileMenu);
            app.loadVstCaseMenu.MenuSelectedFcn = createCallbackFcn(app, @loadVstCaseMenu_Callback, true);
            app.loadVstCaseMenu.Text = 'Load VST Case File';
            app.loadVstCaseMenu.Tag = 'loadVstCaseMenu';
            app.saveVstCaseMenu = uimenu(app.fileMenu);
            app.saveVstCaseMenu.MenuSelectedFcn = createCallbackFcn(app, @saveVstCaseMenu_Callback, true);
            app.saveVstCaseMenu.Text = 'Save VST Case File';
            app.saveVstCaseMenu.Tag = 'saveVstCaseMenu';
            app.GridLayout = uigridlayout(app.vs_mainGUI);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = 'vst_20230324.png';
            app.text2 = uilabel(app.GridLayout2);
            app.text2.Tag = 'text2';
            app.text2.Text = {'KSP Trajectory Optimization Tool'; 'Vehicle Sizing Tool'};
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.uipanel8 = uipanel(app.GridLayout3);
            app.uipanel8.Title = 'Output';
            app.uipanel8.Tag = 'uipanel8';
            app.GridLayout4 = uigridlayout(app.uipanel8);
            app.outputText = uitextarea(app.GridLayout4);
            app.outputText.Tag = 'outputText';
            app.outputText.Editable = 'off';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.runSizingButton = uibutton(app.GridLayout5, 'push');
            app.runSizingButton.ButtonPushedFcn = createCallbackFcn(app, @runSizingButton_Callback, true);
            app.runSizingButton.Tag = 'runSizingButton';
            app.runSizingButton.Text = 'Run Sizing Analysis';
            app.uipanel1 = uipanel(app.GridLayout5);
            app.uipanel1.Title = 'Mission Phases';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout6 = uigridlayout(app.uipanel1);
            app.uipanel3 = uipanel(app.GridLayout6);
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout7 = uigridlayout(app.uipanel3);
            app.phaseNameText = uieditfield(app.GridLayout7, 'text');
            app.phaseNameText.ValueChangedFcn = createCallbackFcn(app, @phaseNameText_Callback, true);
            app.phaseNameText.Tag = 'phaseNameText';
            app.phaseNameText.Tooltip = 'The name of this mission phase.';
            app.phaseNameText.Value = 'Phase Name';
            app.uipanel5 = uipanel(app.GridLayout7);
            app.uipanel5.Title = 'Phase Parameters';
            app.uipanel5.Tag = 'uipanel5';
            app.GridLayout8 = uigridlayout(app.uipanel5);
            app.text7 = uilabel(app.GridLayout8);
            app.text7.Tag = 'text7';
            app.text7.Tooltip = 'The total target delta-v for the selected phase.';
            app.text7.Text = 'Phase Delta-V';
            app.phaseDvText = uieditfield(app.GridLayout8, 'text');
            app.phaseDvText.ValueChangedFcn = createCallbackFcn(app, @phaseDvText_Callback, true);
            app.phaseDvText.Tag = 'phaseDvText';
            app.phaseDvText.Tooltip = 'The total target delta-v for the selected phase.';
            app.phaseDvText.Value = '1000';
            app.text8 = uilabel(app.GridLayout8);
            app.text8.Tag = 'text8';
            app.text8.Text = 'm/s';
            app.Image3 = uiimage(app.GridLayout8);
            app.Image3.ImageSource = 'delta.png';
            app.GridLayout9 = uigridlayout(app.GridLayout6);
            app.deletePhaseButton = uibutton(app.GridLayout9, 'push');
            app.deletePhaseButton.ButtonPushedFcn = createCallbackFcn(app, @deletePhaseButton_Callback, true);
            app.deletePhaseButton.Tag = 'deletePhaseButton';
            app.deletePhaseButton.Icon = 'cancel.png';
            app.deletePhaseButton.Text = 'Delete Mission Phase';
            app.addPhaseButton = uibutton(app.GridLayout9, 'push');
            app.addPhaseButton.ButtonPushedFcn = createCallbackFcn(app, @addPhaseButton_Callback, true);
            app.addPhaseButton.Tag = 'addPhaseButton';
            app.addPhaseButton.Icon = 'add.png';
            app.addPhaseButton.Text = 'Add Mission Phase';
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.phasesListbox = uilistbox(app.GridLayout10);
            app.phasesListbox.Items = {'Listbox'};
            app.phasesListbox.ValueChangedFcn = createCallbackFcn(app, @phasesListbox_Callback, true);
            app.phasesListbox.Tag = 'phasesListbox';
            app.phasesListbox.Value = 'Listbox';
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.movePhaseUpButton = uibutton(app.GridLayout11, 'push');
            app.movePhaseUpButton.ButtonPushedFcn = createCallbackFcn(app, @movePhaseUpButton_Callback, true);
            app.movePhaseUpButton.Tag = 'movePhaseUpButton';
            app.movePhaseUpButton.Icon = 'up-arrow.png';
            app.movePhaseUpButton.Tooltip = 'Move phase up in list.';
            app.movePhaseUpButton.Text = '­';
            app.movePhaseDownButton = uibutton(app.GridLayout11, 'push');
            app.movePhaseDownButton.ButtonPushedFcn = createCallbackFcn(app, @movePhaseDownButton_Callback, true);
            app.movePhaseDownButton.Tag = 'movePhaseDownButton';
            app.movePhaseDownButton.Icon = 'down-arrow.png';
            app.movePhaseDownButton.Tooltip = 'Move phase down in list.';
            app.movePhaseDownButton.Text = '';
            app.stagesPanel = uipanel(app.GridLayout5);
            app.stagesPanel.Title = 'Stages (Phase = <phaseName>)';
            app.stagesPanel.Tag = 'stagesPanel';
            app.GridLayout12 = uigridlayout(app.stagesPanel);
            app.uipanel4 = uipanel(app.GridLayout12);
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout13 = uigridlayout(app.uipanel4);
            app.stageNameText = uieditfield(app.GridLayout13, 'text');
            app.stageNameText.ValueChangedFcn = createCallbackFcn(app, @stageNameText_Callback, true);
            app.stageNameText.Tag = 'stageNameText';
            app.stageNameText.Tooltip = 'The name of this stage.';
            app.stageNameText.Value = 'Stage Name';
            app.uipanel7 = uipanel(app.GridLayout13);
            app.uipanel7.Title = 'Stage Parameters';
            app.uipanel7.Tag = 'uipanel7';
            app.GridLayout14 = uigridlayout(app.uipanel7);
            app.text17 = uilabel(app.GridLayout14);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Stage Central Body';
            app.isPayloadCheckbox = uicheckbox(app.GridLayout14);
            app.isPayloadCheckbox.ValueChangedFcn = createCallbackFcn(app, @isPayloadCheckbox_Callback, true);
            app.isPayloadCheckbox.Tag = 'isPayloadCheckbox';
            app.isPayloadCheckbox.Tooltip = 'Check this box to ignore optimizing the dry mass of this stage.  This mass will be fixed.';
            app.isPayloadCheckbox.Text = 'This stage is a payload?';
            app.GridLayout15 = uigridlayout(app.GridLayout14);
            app.stageIspLabel = uilabel(app.GridLayout15);
            app.stageIspLabel.Tag = 'stageIspLabel';
            app.stageIspLabel.Text = 'Stage Isp';
            app.stageIspText = uieditfield(app.GridLayout15, 'text');
            app.stageIspText.ValueChangedFcn = createCallbackFcn(app, @stageIspText_Callback, true);
            app.stageIspText.Tag = 'stageIspText';
            app.stageIspText.Tooltip = 'The specific impulse of the engine(s) on this stage.  May be a mass flow rate weighted average if mutliple different types of engines are present.';
            app.stageIspText.Value = '1000';
            app.text12 = uilabel(app.GridLayout15);
            app.text12.Tag = 'text12';
            app.text12.Text = 'sec';
            app.GridLayout16 = uigridlayout(app.GridLayout14);
            app.text13 = uilabel(app.GridLayout16);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Dry Mass Fraction';
            app.dryMassFractionText = uieditfield(app.GridLayout16, 'text');
            app.dryMassFractionText.ValueChangedFcn = createCallbackFcn(app, @dryMassFractionText_Callback, true);
            app.dryMassFractionText.Tag = 'dryMassFractionText';
            app.dryMassFractionText.Tooltip = 'The fraction of mass on the stage that is "dry mass" and not propellant.  Must be between 0 and 1.  The higher the number, the more dry mass is on the stage.';
            app.dryMassFractionText.Value = '1000';
            app.text14 = uilabel(app.GridLayout16);
            app.text14.Tag = 'text14';
            app.text14.Text = '';
            app.GridLayout17 = uigridlayout(app.GridLayout14);
            app.text15 = uilabel(app.GridLayout17);
            app.text15.Tag = 'text15';
            app.text15.Text = 'T/W Ratio';
            app.desiredT2WText = uieditfield(app.GridLayout17, 'text');
            app.desiredT2WText.ValueChangedFcn = createCallbackFcn(app, @desiredT2WText_Callback, true);
            app.desiredT2WText.Tag = 'desiredT2WText';
            app.desiredT2WText.Tooltip = 'The desired thrust to weight ratio for this stage and all stages that it carries above it.';
            app.desiredT2WText.Value = '1000';
            app.text16 = uilabel(app.GridLayout17);
            app.text16.Tag = 'text16';
            app.text16.Text = '';
            app.GridLayout18 = uigridlayout(app.GridLayout14);
            app.text18 = uilabel(app.GridLayout18);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Fixed Pyld Mass';
            app.payloadMassText = uieditfield(app.GridLayout18, 'text');
            app.payloadMassText.ValueChangedFcn = createCallbackFcn(app, @payloadMassText_Callback, true);
            app.payloadMassText.Tag = 'payloadMassText';
            app.payloadMassText.Value = '0.5';
            app.text19 = uilabel(app.GridLayout18);
            app.text19.Tag = 'text19';
            app.text19.Text = 'mT';
            app.GridLayout22 = uigridlayout(app.GridLayout14);
            app.bodyCombo = uidropdown(app.GridLayout22);
            app.bodyCombo.Items = {'Pop-up Menu'};
            app.bodyCombo.ValueChangedFcn = createCallbackFcn(app, @bodyCombo_Callback, true);
            app.bodyCombo.Tag = 'bodyCombo';
            app.bodyCombo.Tooltip = 'The central body that the required thrust to meet desired thrust to weight ratio is computed with respect to.';
            app.bodyCombo.Value = 'Pop-up Menu';
            app.Image2 = uiimage(app.GridLayout22);
            app.Image2.ImageSource = 'jupiter.png';
            app.GridLayout19 = uigridlayout(app.GridLayout12);
            app.deleteStageButton = uibutton(app.GridLayout19, 'push');
            app.deleteStageButton.ButtonPushedFcn = createCallbackFcn(app, @deleteStageButton_Callback, true);
            app.deleteStageButton.Tag = 'deleteStageButton';
            app.deleteStageButton.Icon = 'cancel.png';
            app.deleteStageButton.Text = 'Delete Stage';
            app.addStageButton = uibutton(app.GridLayout19, 'push');
            app.addStageButton.ButtonPushedFcn = createCallbackFcn(app, @addStageButton_Callback, true);
            app.addStageButton.Tag = 'addStageButton';
            app.addStageButton.Icon = 'add.png';
            app.addStageButton.Text = 'Add Stage';
            app.GridLayout20 = uigridlayout(app.GridLayout19);
            app.stagesListbox = uilistbox(app.GridLayout20);
            app.stagesListbox.Items = {'Listbox'};
            app.stagesListbox.ValueChangedFcn = createCallbackFcn(app, @stagesListbox_Callback, true);
            app.stagesListbox.Tag = 'stagesListbox';
            app.stagesListbox.Value = 'Listbox';
            app.GridLayout21 = uigridlayout(app.GridLayout20);
            app.moveStageUpButton = uibutton(app.GridLayout21, 'push');
            app.moveStageUpButton.ButtonPushedFcn = createCallbackFcn(app, @moveStageUpButton_Callback, true);
            app.moveStageUpButton.Tag = 'moveStageUpButton';
            app.moveStageUpButton.Icon = 'up-arrow.png';
            app.moveStageUpButton.Tooltip = 'Move stage up in list.';
            app.moveStageUpButton.Text = '';
            app.moveStageDownButton = uibutton(app.GridLayout21, 'push');
            app.moveStageDownButton.ButtonPushedFcn = createCallbackFcn(app, @moveStageDownButton_Callback, true);
            app.moveStageDownButton.Tag = 'moveStageDownButton';
            app.moveStageDownButton.Icon = 'down-arrow.png';
            app.moveStageDownButton.Tooltip = 'Move stage down in list.';
            app.moveStageDownButton.Text = '';
            % Show the figure after all components are created
            app.vs_mainGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vs_mainGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.vs_mainGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)vs_mainGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.vs_mainGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.vs_mainGUI)
        end
    end
end